classdef DecodeFeatures < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        DecodeFeaturesforreferenceLabel  matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        Features_Ch2Panel        matlab.ui.container.Panel
        MAVMVZXSSCWFLACCLabel_2  matlab.ui.control.Label
        ACCButton_2              matlab.ui.control.StateButton
        WFLButton_2              matlab.ui.control.StateButton
        SSCButton_2              matlab.ui.control.StateButton
        ZXButton_2               matlab.ui.control.StateButton
        MVButton_2               matlab.ui.control.StateButton
        MAVButton_2              matlab.ui.control.StateButton
        Ch1HexEditField_2        matlab.ui.control.EditField
        HexEditFieldLabel_2      matlab.ui.control.Label
        Ch1DecEditField_2        matlab.ui.control.NumericEditField
        DecEditFieldLabel_2      matlab.ui.control.Label
        UpdateButtons            matlab.ui.control.Button
        UpdateNumberButton       matlab.ui.control.Button
        Features_Ch1Panel        matlab.ui.container.Panel
        MAVMVZXSSCWFLACCLabel    matlab.ui.control.Label
        ACCButton_1              matlab.ui.control.StateButton
        WFLButton_1              matlab.ui.control.StateButton
        SSCButton_1              matlab.ui.control.StateButton
        ZXButton_1               matlab.ui.control.StateButton
        MVButton_1               matlab.ui.control.StateButton
        MAVButton_1              matlab.ui.control.StateButton
        Ch1HexEditField_1        matlab.ui.control.EditField
        HexEditFieldLabel_1      matlab.ui.control.Label
        Ch1DecEditField_1        matlab.ui.control.NumericEditField
        DecEditFieldLabel_1      matlab.ui.control.Label
    end


    properties (Access = private)
        Features1=uint8(0); % Description
        Features2=uint8(0);
        callingFigure=[];
    end

    properties (Access = public)
%      Features=[2 5]; % Description
    end
    
    methods (Access = public)
        
        function chanBits = chanFeatureBits(app,f)
            featureBits=f;
           chanBits=0;
            for i=[1:5]
                chanBits=chanBits+bitget(featureBits,i);
            end
            chanBits=chanBits+bitget(featureBits,7)*2;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
           myFeatures=varargin{1};
            a=myFeatures(1);
            b=myFeatures(2);
            app.Ch1DecEditField_1.Value=a;
            app.Ch1DecEditField_2.Value=b;
            app.Ch1HexEditField_1.Value=dec2hex(a,2);
            app.Ch1HexEditField_2.Value=dec2hex(b,2);
            if nargin>1
                app.callingFigure=varargin{2};
            end
            UpdateButtonsPushed(app, []) %run button pushed event at startup
        end

        % Button pushed function: UpdateButtons
        function UpdateButtonsPushed(app, event)
            % convert the number represented by the decimal input to buttons.
            % Need to convert the number to an unsigned 8 bit number then look at the bits
            % Bits:
            % 1 MAV
            % 2 MV
            % 3 ZX
            % 4 SSC
            % 5 WFL
            % 6 --
            % 7 ACC
            % 8 --
            app.Features1=uint8((app.Ch1DecEditField_1.Value));
            app.MAVButton_1.Value=bitget(app.Features1,1);
            app.MVButton_1.Value=bitget(app.Features1,2);
            app.ZXButton_1.Value=bitget(app.Features1,3);
            app.SSCButton_1.Value=bitget(app.Features1,4);
            app.WFLButton_1.Value=bitget(app.Features1,5);
            app.ACCButton_1.Value=bitget(app.Features1,7);
            
            app.Features2=uint8((app.Ch1DecEditField_2.Value));
            app.MAVButton_2.Value=bitget(app.Features2,1);
            app.MVButton_2.Value=bitget(app.Features2,2);
            app.ZXButton_2.Value=bitget(app.Features2,3);
            app.SSCButton_2.Value=bitget(app.Features2,4);
            app.WFLButton_2.Value=bitget(app.Features2,5);
            app.ACCButton_2.Value=bitget(app.Features2,7);
            
        end

        % Value changed function: Ch1DecEditField_1
        function Ch1DecEditField_1ValueChanged(app, event)

            app.Ch1HexEditField_1.Value=dec2hex(app.Ch1DecEditField_1.Value,2);
            app.UpdateNumberButtonPushed();
        end

        % Value changed function: Ch1HexEditField_1
        function Ch1HexEditField_1ValueChanged(app, event)
            app.Ch1DecEditField_1.Value = hex2dec(app.Ch1HexEditField_1.Value);
            app.UpdateNumberButtonPushed();
        end

        % Button pushed function: UpdateNumberButton
        function UpdateNumberButtonPushed(app, event)
            %convert buttons to bits using set bit.
            app.Features1=bitset(app.Features1,1,app.MAVButton_1.Value);
            app.Features1=bitset(app.Features1,2,app.MVButton_1.Value);
            app.Features1=bitset(app.Features1,3,app.ZXButton_1.Value);
            app.Features1=bitset(app.Features1,4,app.SSCButton_1.Value);
            app.Features1=bitset(app.Features1,5,app.WFLButton_1.Value);
            app.Features1=bitset(app.Features1,7,app.ACCButton_1.Value);
            % check for valid features
            cbits1=app.chanFeatureBits(app.Features1);
            if cbits1<=4
                app.Ch1DecEditField_1.Value=double(app.Features1);
                app.Ch1HexEditField_1.Value=dec2hex(app.Ch1DecEditField_1.Value,2);
            else
               c1=sprintf('Wrong number of bytes\nCh 1, %d bytes\nAcc counts as two.\nFour allowed per channel',cbits1);
               fig = app.UIFigure;
               uialert(fig,c1,'Invalid Buttons');
            end
            app.Features2=bitset(app.Features2,1,app.MAVButton_2.Value);
            app.Features2=bitset(app.Features2,2,app.MVButton_2.Value);
            app.Features2=bitset(app.Features2,3,app.ZXButton_2.Value);
            app.Features2=bitset(app.Features2,4,app.SSCButton_2.Value);
            app.Features2=bitset(app.Features2,5,app.WFLButton_2.Value);
            app.Features2=bitset(app.Features2,7,app.ACCButton_2.Value);
             cbits2=app.chanFeatureBits(app.Features2);
            if cbits2<=4
            app.Ch1DecEditField_2.Value=double(app.Features2);
            app.Ch1HexEditField_2.Value=dec2hex(app.Ch1DecEditField_2.Value,2);
            else
               c2=sprintf('Wrong number of bytes\nCh 2, %d bytes',cbits2);
               fig = app.UIFigure;
               uialert(fig,c2,'Invalid Buttons');
            end
            % need to add check here for invalid selection  - only 4 can be selected and Acc counts as 2
        end

        % Value changed function: Ch1DecEditField_2
        function Ch1DecEditField_2ValueChanged(app, event)
             app.Ch1HexEditField_2.Value=dec2hex(app.Ch1DecEditField_2.Value,2); 
             app.UpdateNumberButtonPushed();
        end

        % Value changed function: Ch1HexEditField_2
        function Ch1HexEditField_2ValueChanged(app, event)
            app.Ch1DecEditField_2.Value = hex2dec(app.Ch1HexEditField_2.Value);
            app.UpdateNumberButtonPushed();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
             % if I add rm help, here is where I would close those handles
            if ishandle(app.callingFigure) % check that is is valid
                figure(app.callingFigure); % bring to front
            end
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Features_Ch1Panel = uipanel(app.UIFigure);
            app.Features_Ch1Panel.Title = 'Features_Ch1';
            app.DecEditFieldLabel_1 = uilabel(app.Features_Ch1Panel);
            app.DecEditFieldLabel_1.Text = 'Dec';
            app.Ch1DecEditField_1 = uieditfield(app.Features_Ch1Panel, 'numeric');
            app.Ch1DecEditField_1.ValueChangedFcn = createCallbackFcn(app, @Ch1DecEditField_1ValueChanged, true);
            app.Ch1DecEditField_1.Value = 81;
            app.HexEditFieldLabel_1 = uilabel(app.Features_Ch1Panel);
            app.HexEditFieldLabel_1.Text = 'Hex';
            app.Ch1HexEditField_1 = uieditfield(app.Features_Ch1Panel, 'text');
            app.Ch1HexEditField_1.ValueChangedFcn = createCallbackFcn(app, @Ch1HexEditField_1ValueChanged, true);
            app.Ch1HexEditField_1.Value = '51';
            app.MAVButton_1 = uibutton(app.Features_Ch1Panel, 'state');
            app.MAVButton_1.Tooltip = {'Mean Absolute Value'};
            app.MAVButton_1.Text = 'MAV';
            app.MAVButton_1.Value = true;
            app.MVButton_1 = uibutton(app.Features_Ch1Panel, 'state');
            app.MVButton_1.Tooltip = {'Mean Value (int8)'};
            app.MVButton_1.Text = 'MV';
            app.ZXButton_1 = uibutton(app.Features_Ch1Panel, 'state');
            app.ZXButton_1.Tooltip = {'Zero Crossing'};
            app.ZXButton_1.Text = 'ZX';
            app.SSCButton_1 = uibutton(app.Features_Ch1Panel, 'state');
            app.SSCButton_1.Tooltip = {'Slope Sign Change'};
            app.SSCButton_1.Text = 'SSC';
            app.WFLButton_1 = uibutton(app.Features_Ch1Panel, 'state');
            app.WFLButton_1.Tooltip = {'Waveform Length'};
            app.WFLButton_1.Text = 'WFL';
            app.WFLButton_1.Value = true;
            app.ACCButton_1 = uibutton(app.Features_Ch1Panel, 'state');
            app.ACCButton_1.Tooltip = {'Acc X, Y'};
            app.ACCButton_1.Text = 'ACC';
            app.ACCButton_1.Value = true;
            app.MAVMVZXSSCWFLACCLabel = uilabel(app.Features_Ch1Panel);
            app.MAVMVZXSSCWFLACCLabel.Text = {'MAV'; 'MV'; 'ZX'; 'SSC'; 'WFL'; '--'; 'ACC'; '--'};
            app.UpdateNumberButton = uibutton(app.UIFigure, 'push');
            app.UpdateNumberButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateNumberButtonPushed, true);
            app.UpdateNumberButton.Tooltip = {'change number to match buttons'};
            app.UpdateNumberButton.Text = {'Update'; 'Number'};
            app.UpdateButtons = uibutton(app.UIFigure, 'push');
            app.UpdateButtons.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonsPushed, true);
            app.UpdateButtons.Tooltip = {'change buttons to match numbers'};
            app.UpdateButtons.Text = {'Update'; 'Buttons'};
            app.Features_Ch2Panel = uipanel(app.UIFigure);
            app.Features_Ch2Panel.Title = 'Features_Ch2';
            app.DecEditFieldLabel_2 = uilabel(app.Features_Ch2Panel);
            app.DecEditFieldLabel_2.Text = 'Dec';
            app.Ch1DecEditField_2 = uieditfield(app.Features_Ch2Panel, 'numeric');
            app.Ch1DecEditField_2.ValueChangedFcn = createCallbackFcn(app, @Ch1DecEditField_2ValueChanged, true);
            app.Ch1DecEditField_2.Value = 81;
            app.HexEditFieldLabel_2 = uilabel(app.Features_Ch2Panel);
            app.HexEditFieldLabel_2.Text = 'Hex';
            app.Ch1HexEditField_2 = uieditfield(app.Features_Ch2Panel, 'text');
            app.Ch1HexEditField_2.ValueChangedFcn = createCallbackFcn(app, @Ch1HexEditField_2ValueChanged, true);
            app.Ch1HexEditField_2.Value = '51';
            app.MAVButton_2 = uibutton(app.Features_Ch2Panel, 'state');
            app.MAVButton_2.Tooltip = {'Mean Absolute Value'};
            app.MAVButton_2.Text = 'MAV';
            app.MAVButton_2.Value = true;
            app.MVButton_2 = uibutton(app.Features_Ch2Panel, 'state');
            app.MVButton_2.Tooltip = {'Mean Value (int8)'};
            app.MVButton_2.Text = 'MV';
            app.ZXButton_2 = uibutton(app.Features_Ch2Panel, 'state');
            app.ZXButton_2.Tooltip = {'Zero Crossing'};
            app.ZXButton_2.Text = 'ZX';
            app.SSCButton_2 = uibutton(app.Features_Ch2Panel, 'state');
            app.SSCButton_2.Tooltip = {'Slope Sign Change'};
            app.SSCButton_2.Text = 'SSC';
            app.WFLButton_2 = uibutton(app.Features_Ch2Panel, 'state');
            app.WFLButton_2.Tooltip = {'Waveform Length'};
            app.WFLButton_2.Text = 'WFL';
            app.WFLButton_2.Value = true;
            app.ACCButton_2 = uibutton(app.Features_Ch2Panel, 'state');
            app.ACCButton_2.Tooltip = {'Acc, Z, counter'};
            app.ACCButton_2.Text = 'ACC';
            app.ACCButton_2.Value = true;
            app.MAVMVZXSSCWFLACCLabel_2 = uilabel(app.Features_Ch2Panel);
            app.MAVMVZXSSCWFLACCLabel_2.Text = {'MAV'; 'MV'; 'ZX'; 'SSC'; 'WFL'; '--'; 'ACC'; '--'};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Note:  Making changes here, is for reference only.  '; 'They do not currently change the vallues in the calling app'};
            app.DecodeFeaturesforreferenceLabel = uilabel(app.UIFigure);
            app.DecodeFeaturesforreferenceLabel.Text = {'Decode Features (for reference)'; 'Four bytes per channel'; 'Acc counts for 2 bytes on each channel'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DecodeFeatures(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end