classdef accSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Menu                        matlab.ui.container.Menu
        OpenMenu                    matlab.ui.container.Menu
        SaveMenu                    matlab.ui.container.Menu
        SaveAsMenu                  matlab.ui.container.Menu
        LogMenu                     matlab.ui.container.Menu
        NewFileMenu                 matlab.ui.container.Menu
        RestartNormalScriptsButton  matlab.ui.control.Button
        Aux1ScriptOnlyNoStimButton  matlab.ui.control.Button
        LogFileEditField            matlab.ui.control.EditField
        LogFileEditFieldLabel       matlab.ui.control.Label
        Aux1Panel                   matlab.ui.container.Panel
        WriteAuxButton              matlab.ui.control.Button
        ReadAuxButton               matlab.ui.control.Button
        AccNodeDropDown             matlab.ui.control.DropDown
        NodeDropDownLabel           matlab.ui.control.Label
        DirDropDown                 matlab.ui.control.DropDown
        DirDropDownLabel            matlab.ui.control.Label
        FilterDepthEditField        matlab.ui.control.NumericEditField
        FilterDepthEditFieldLabel   matlab.ui.control.Label
        GainEditField               matlab.ui.control.NumericEditField
        GainEditFieldLabel          matlab.ui.control.Label
        OffsetEditField             matlab.ui.control.NumericEditField
        OffsetEditFieldLabel        matlab.ui.control.Label
        ChanDropDown                matlab.ui.control.DropDown
        ChanDropDownLabel           matlab.ui.control.Label
        RampDownEditField           matlab.ui.control.NumericEditField
        RampDownEditFieldLabel      matlab.ui.control.Label
        RampUpEditField             matlab.ui.control.NumericEditField
        RampUpEditFieldLabel        matlab.ui.control.Label
        MethodDropDown              matlab.ui.control.DropDown
        MethodDropDownLabel         matlab.ui.control.Label
        DelayEditField              matlab.ui.control.NumericEditField
        DelayEditFieldLabel         matlab.ui.control.Label
        ParamFileEditField          matlab.ui.control.EditField
        ParamFileEditFieldLabel     matlab.ui.control.Label
        Panel                       matlab.ui.container.Panel
        UserIDEditField             matlab.ui.control.EditField
        ParticipantLabel            matlab.ui.control.Label
        UserEditField               matlab.ui.control.NumericEditField
        NetworkPanel                matlab.ui.container.Panel
        WaitButton                  matlab.ui.control.Button
        PatientLamp                 matlab.ui.control.Lamp
        StartPDOsButton             matlab.ui.control.Button
        NetworkLamp                 matlab.ui.control.Lamp
        OffButton                   matlab.ui.control.Button
        OnButton                    matlab.ui.control.Button
        ClearMessageButton          matlab.ui.control.Button
        Messages                    matlab.ui.control.TextArea
        StimNodesEditField          matlab.ui.control.EditField
        StimNodesEditFieldLabel     matlab.ui.control.Label
        MESNodeEditField            matlab.ui.control.NumericEditField
        MESNodeEditFieldLabel       matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        PGTab                       matlab.ui.container.Tab
        NodeActiveDepthLabel        matlab.ui.control.Label
        plotOnCycleEditField        matlab.ui.control.NumericEditField
        plotOnCycleEditFieldLabel   matlab.ui.control.Label
        LogOnButton                 matlab.ui.control.StateButton
        ZLabel                      matlab.ui.control.Label
        YLabel                      matlab.ui.control.Label
        XLabel                      matlab.ui.control.Label
        SourceSwitch                matlab.ui.control.Switch
        SourceSwitchLabel           matlab.ui.control.Label
        ViewAccButton               matlab.ui.control.StateButton
        NodeDisplayDropDown         matlab.ui.control.DropDown
        StatusButton                matlab.ui.control.Button
        TpdoActiveTable             matlab.ui.control.Table
        CommandAxes                 matlab.ui.control.UIAxes
        AccAxes                     matlab.ui.control.UIAxes
        AuxParTab                   matlab.ui.container.Tab
        BPTab                       matlab.ui.container.Tab
        SyncIntEditField            matlab.ui.control.NumericEditField
        SyncIntEditFieldLabel       matlab.ui.control.Label
    end

    % I want to put all the data into variables first, then update the display, in order to make it easeier to load and
    % save the data to mat file later.
    
    properties (Access = public)
        %        rmParam=[];
        nnp=[];
        auxfilename;
        pathname;
        Aux1Par=[1,0,26,13,177,1,128,20,10]; % default for using Acc with node 4Y
        TpdoActive= logical([0,0,0,0,0,0]');% logical column array
        TpdoTable=[];
        comIdx=1;  % command index
        %plotting
        plotbufx=[];
        plotbufy=[];
        plotbufz=[];
        plotbufc=[];
        px=[]; % plot x handle
        py=[]; % plot y handle
        pz=[]; % plot z handle
        pc=[]; % plot command handle
        y1=[]; % handle for Y line at offset
        y2=[]; % handle for y line at max
        lgd=[]; % legend handle
        
        % explore option of buffering all 28 data points in addition to logging them to facilitate switching between
        % nodes or plotting multiple nodes.
        plotallbuf=[]; % all recorded nodes
        % timer
        accTimer=[];
        plotThisCycle=3; %plot every fourth cycle making the update 5Hz
        plotCycle=0; % increment every cycle
        ODval=double(zeros(1,28)); % array for read
        accsamp=50; %start with 5 seconds of data
        startSi=164; % where to start reading subindices of 1F53
        plotIndex=[1,5,9,13,17,21,25];  %starting subindex based on dropdown selection
        readlength=28; %subindices to read
        nodeMap=[9,1,2,3,4,5,6]; %how the subindices are arranged
        % if I make the dropdown match the nodeMap, then I can read the plotindex=(index*4)-3
        
        lastcounter=0;
        plotthis=0;
        oldtime=[];
        rabuffer=double([128,128,128,128,128,128,128, 128,128,128]);  %start with entire buffer so that we can update on the fly
        raptr=1;
        
        % logging of data
        logfilename=[];
        logfolder=[];
        logID=[]; %file ID for the log file
        
        callingFigure=[]; %handle to calling figure
        % Code for auto file open
         userFolders=struct;  % userfiles, code, user, config, data, log
         defaultSuffix='_Aux1_Parameters.mat';
         defaultName='P00_Aux1_Parameters.mat';
    end
    
    methods (Access = private)
        
        
    end
    
    methods (Access = public)
        
        function ClearMessages(app)
            app.Messages.Value=''; % clear messages
        end
        
        function TopMessage(app,message)
            app.Messages.Value=vertcat(message, string(app.Messages.Value)); % put new message on top
        end
        
        function quit = checkDoSave(app)
            % if the structure has changed, then need to check whether the user wants to save the current file before
            % opening another.
            quit = false;
            if ~isempty(app.auxfilename) %file already exists
                
                %check if any changes have been made
                savedValues = load(app.auxfilename);
                if ~isequal(savedValues.Aux1Par, app.Aux1Par)
                    
                    userResp = questdlg(sprintf(['Changes detected in Aux parameter file.\n Do you want to save changes to:\n',...
                        '"%s"?\n\nHit Cancel and choose File/Save As if you want to save into a new file'], app.auxfilename),...
                        'Aux Parameter File');
                    if isequal(userResp, 'Yes')
                        app.SaveMenuSelected();
                    elseif isequal(userResp, 'No')
                        %nothing to do here
                    else %Cancel
                        quit = true;
                    end
                end
            elseif ~isempty(app.Aux1Par) %no file exists yet, but something has been done
                userResp = questdlg('Save Aux parameter File?','Aux Parameter File');
                if isequal(userResp, 'Yes')
                    app.SaveAsMenuSelected();
                elseif isequal(userResp, 'No')
                    %nothing to do here
                else %Cancel
                    quit = true;
                end
            end
        end
        
        function writeErrCheck(app,result,message,node)
            if isempty(result)
                dmessage=strcat('failed to write ',message,' ',num2str(node));
                app.TopMessage(dmessage);
            end
            
        end
        
        function defaultTpdoTable(app)
            % determine length by PG nodes
            Node=str2num(app.StimNodesEditField.Value)'; %numerical list of nodes, transposed
            nPG=size(Node,1);
            Active=false(nPG,1); %logical array of 0's
            Depth=ones(nPG,1); % default table assumes all use filtered data
            % create list of names based on PG nodes
            app.TpdoTable=table(Node,Active,Depth);
            app.TpdoActiveTable.Data=app.TpdoTable;
            % generate table
            % update table
            % made rows editable
        end
        
        
        function updateAcc(app,obj,event) % not sure whether obj and event are necessary for timer function since not used
            resp=app.nnp.read(7,'1f53',app.startSi,app.readlength,'uint8');
            if(length(resp)==app.readlength) % valid packet
                app.ODval=double(resp);
                % since acc samples at 40Hz, can skip the counter condition, but will log anyway
                % update buffers (start with just x,y,z but plan for more later
                pli=app.plotIndex(app.NodeDisplayDropDown.Value); % the value is mapped to the index in the read buffer
                new(1)=app.ODval(pli); % x
                new(2)=app.ODval(pli+1); % y
                new(3)=app.ODval(pli+2); % z
                if app.SourceSwitch.Value=="PM"
                    % Get Aux command at 1F53.21 which is 33 decimal
                    resp=app.nnp.read(7,'1f53',33,1,'uint8');
                    if(length(resp)==1) % valid packet
                        newAuxCom=resp; %use different name to keep separate
                    else
                        newAuxCom=0;
                    end
                else
                    newAuxCom=0;  % should I report prior command if error reading or report 0?
                end
                % Here I am going to keep track of the calculated command even if I am reading actual.  I could plot
                % both together for comparison
                % New Command is an N point running average.  I need to keep track of prior points
                newCom=app.commandRA(new(app.comIdx)); % one of the points already being plotted
                %                 % scale and gain
                newCom=(newCom-app.OffsetEditField.Value)*app.GainEditField.Value;  % should change gain to gain*10 to allow fractions
                newCom=min(255,newCom); % truncate at 255
                newCom=max(newCom,0); % truncate at 0
                app.plotbufx(1:end-1)=app.plotbufx(2:end);
                app.plotbufx(end)=new(1);
                app.plotbufy(1:end-1)=app.plotbufy(2:end);
                app.plotbufy(end)=new(2);
                app.plotbufz(1:end-1)=app.plotbufz(2:end);
                app.plotbufz(end)=new(3);
                % plot command
                app.plotbufc(1:end-1)=app.plotbufc(2:end);
                if app.SourceSwitch.Value=="PM"
                    app.plotbufc(end)=newAuxCom;
                else
                    app.plotbufc(end)=newCom;
                end
                % update the plots
                % Since I am recording at 10hz, I really only need to update every 2 or 3 points
                % Add a counter to keep track and update plots every n points
                %                 if app.plotCycle>=app.plotThisCycle % use >= just in case it gets missed somewhere
                if app.plotCycle>=app.plotOnCycleEditField.Value % make variable for testing
                    app.px.YData=app.plotbufx;
                    app.py.YData=app.plotbufy;
                    app.pz.YData=app.plotbufz;
                    app.pc.YData=app.plotbufc;
                    app.plotCycle=0; % reset counter
                else
                    app.plotCycle=app.plotCycle+1; % increment the counter
                end
                % log the data
                if app.LogOnButton.Value % log to file
                    fprintf(app.logID,'%2.2f, ',app.ODval(1:app.readlength)); % print without the carriage return
                    fprintf(app.logID,'%2.2f\n',newCom); % add command and print carriage return
                end
            end
        end
        
        function startAcc(app)
            app.oldtime=now();
            if app.nnp.getNetworkStatus()==1  % if network is on, then proceed, otherwise send message
                % check if in patient mode
                %                app.saveRadio();
                % for max bandwidth, we need to eliminate radio retries, shorten timeouts, and turn of WOR
                %                app.nnp.setRadio('timeout', 200, 'retries', 5');
                %                app.nnp.worOff();
                %                app.disableGraphInteractions(); % disable while running
                if app.LogOnButton.Value==1 %log the data
                    app.logID=fopen(app.logfilename,'a+'); % open the file and append ,if already oepn will append
                    app.TopMessage('Log file opened');
                end
                start(app.accTimer);
            else
                app.TopMessage('Network is not ON');
                app.ViewAccButton.Value=false;
            end
            % Here I could disable the interactions and reenable them when
            % finished recording.
        end
        
        function stopAcc(app)
            stop(app.accTimer);
            if app.LogOnButton.Value
                if ~isempty(app.logID) % cannot use fopen with empty variable
                    if ~isempty(fopen(app.logID)) % check whether file is already open, 1 means not open
                        fclose(app.logID); % close this file before opening another
                    end
                end
                
            end
            %            app.restoreRadio();
            %            app.enableGraphInteractions();
        end
        
        function results = commandRA(app,newpt)
            % newpt is the most recent point
            depth=app.FilterDepthEditField.Value; %number of points to average
            ptr=app.raptr;
            % if depth has changed and is > depth, then reset
            ptr=ptr+1;
            if ptr>depth
                ptr=1;
                % replace current point
            end
            app.rabuffer(ptr)=newpt;
            results=sum(app.rabuffer(1:depth))/depth;
            app.raptr=ptr;
        end
        
        function updateYlines(app)
            % display gain and offset
            y1v=app.OffsetEditField.Value;
            app.y1.Value=y1v;
            y2v=y1v+255/app.GainEditField.Value;
            app.y2.Value=y2v;
        end
        
        function UpdateAuxVariables(app)
            % assume the app.Aux1Par has been updated by reading file or reading PM
            %app.Aux1Par nine elements
            % 1 Aux1 Control Method
            % 2 Aux Delay Cycles
            % 3 Aux ramp up
            % 4 Aux ramp down
            % 5 Aux1 Acc Chan Subindex
            % 6 Aux1 Acc Orientation
            % 7 Aux Acc Offset
            % 8 Aux Acc Gain
            % 9 Aux1 Acc Filter Depth
            app.MethodDropDown.Value=num2str(app.Aux1Par(1));
            app.DelayEditField.Value=app.Aux1Par(2);
            app.RampUpEditField.Value=app.Aux1Par(3);
            app.RampDownEditField.Value=app.Aux1Par(4);
            if app.Aux1Par(5)>=164
                base=app.Aux1Par(5)-164;
                ind=floor(base/4); %0 based with 9 1 2 3 4 5 6
                %                nodearray=[9,1,2,3,4,5,6];
                chan=mod(base,ind) ; % value for dropdown in 0,1,2
                app.ChanDropDown.Value=num2str(chan);
                nodevalue=num2str(app.Aux1Par(5)-chan);
                app.AccNodeDropDown.Value=nodevalue;
                app.comIdx=str2num(app.ChanDropDown.Value)+1;  % this is not very efficient.  Maybe should not change while running
                
            else
                app.TopMessage('No Acc channel currently selected')
            end
            app.DirDropDown.Value=num2str(app.Aux1Par(6));
            app.OffsetEditField.Value= app.Aux1Par(7);
            app.GainEditField.Value=app.Aux1Par(8);
            app.FilterDepthEditField.Value=app.Aux1Par(9);
        end
        
        function FillAux1Par(app)
            %app.Aux1Par nine elements
            % 1 Aux1 Control Method
            % 2 Aux Delay Cycles
            % 3 Aux ramp up
            % 4 Aux ramp down
            % 5 Aux1 Acc Chan Subindex
            % 6 Aux1 Acc Orientation
            % 7 Aux Acc Offset
            % 8 Aux Acc Gain
            % 9 Aux1 Acc Filter Depth
            app.Aux1Par(1)=str2num(app.MethodDropDown.Value);
            app.Aux1Par(2)=app.DelayEditField.Value;
            app.Aux1Par(3)=app.RampUpEditField.Value;
            app.Aux1Par(4)=app.RampDownEditField.Value;
            app.Aux1Par(5)=str2num(app.AccNodeDropDown.Value)+str2num(app.ChanDropDown.Value);
            app.Aux1Par(6)=str2num(app.DirDropDown.Value);
            app.Aux1Par(7)=app.OffsetEditField.Value;
            app.Aux1Par(8)=app.GainEditField.Value;
            app.Aux1Par(9)=app.FilterDepthEditField.Value;
        end
        
        function getLogfilename(app)
            % check whether a the logID is already open and close it first
            % select a new file
            % if new filename is valid, oen the file
            % update the filename
            % get the current file names
             %here
            userstring=app.userFolders.code; %userID
            folder=app.userFolders.data;
            prefix='_Auxlog';
            % call the dialog box with input values
            %folder=app.logfolder; %start with exisiting folder
            %userstring=sprintf('P%d_',int16(app.UserEditField.Value));
            timestring=sprintf('_%02.0f',datevec(now));            
            tempname=fullfile(folder,[userstring,prefix,timestring,'.csv']);
            [fn, pn]=uiputfile('*.cvs','New Log File',tempname);
            %
            if fn==0
                %ignore
                app.TopMessage('Cancelled');
            else
                app.logfilename=fullfile(pn,fn);
                app.logfolder=pn;
                app.LogFileEditField.Value=app.logfilename;
            end
            
        end
        
        function AuxOnlyScript(app)
            % need to add error checks to each of these
            
            % stop functional scripts using action 9
            result=app.nnp.write(7,'1F53',4,9,'uint8');
            if ~isequal(result,0)
                app.TopMessage('Error sending stop All command');
            end
            pause(.1);
            
            % stop all scripts
            resp=app.nnp.nmt(7,'8F'); % disable scripts, which stops them all
            if resp~=hex2dec('8F')
                app.TopMessage('Error disabling scripts');
            end
            pause(.1);
            
            resp=app.nnp.nmt(7,'8E'); % enable scripts
            if resp~=hex2dec('8E')
                app.TopMessage('Error enabling scripts');
            end
            pause(.1);
            
            if app.nnp.enterWaiting()
                app.TopMessage('Waiting');
            else
                app.TopMessage('Waiting failed');
            end
            
            if app.nnp.networkOn()
                app.TopMessage('Network On');
            else
                app.TopMessage('Network On Failed');
            end
            pause(.4);
            % patient mode
            if app.nnp.enterPatient()
                app.TopMessage('Patient mode');
            else
                app.TopMessage('Patient mode failed');
            end
            pause(.1);
            % Start script 20, Status Prep, which transfers needed data to be read by block reads
            resp=app.nnp.nmt(7,'8D',20);
            if resp~=hex2dec('8D')
                app.TopMessage('Error starting script 20');
            else
                app.TopMessage('Start script 20');
            end
            pause(.1);
            % Start script 16  Aux1 Script
            % If I want stim I need to add write to 1F53.31 for aux1 logical pattern
            resp=app.nnp.nmt(7,'8D',16);
            if resp~=hex2dec('8D')
                app.TopMessage('Error starting script 16');
            else
                app.TopMessage('Start script 16');
            end
            % check NodeStatus to make sure still able to read
            app.networkNodeStatus();
        end
        
        function resetScripts(app)
            % restore scripts to normal operation
            % disable scripts, which stops execution after the currently running script and resets flags at 1F51
            resp=app.nnp.nmt(7,'8F'); % disable scripts, which stops them all
            if resp~=hex2dec('8F')
                app.TopMessage('Error disabling scripts');
            else
                app.TopMessage('Disable Scripts');
            end
            pause(.1);
            
            % enable scripts, which starts the script interpreter running again
            resp=app.nnp.nmt(7,'8E'); % enable scripts
            if resp~=hex2dec('8E')
                app.TopMessage('Error enabling scripts');
            else
                app.TopMessage('Enable Scripts');
            end
            pause(.1);
            
            % run startup script
            resp=app.nnp.nmt(7,'8D',7);
            if resp~=hex2dec('8D')
                app.TopMessage('Error running startup script');
            else
                app.TopMessage('Run Startup Script');
            end
            pause(.1);
            
            % run stopAll, which will reset parameters back where they should be if caught in intermediate state
            result=app.nnp.write(7,'1F53',4,9,'uint8');
            if ~isequal(result,0)
                app.TopMessage('Error sending stop All command');
            else
                app.TopMessage('Stop All Action - to reset variables');
            end
            pause(1);
           % check NodeStatus to make sure still able to read
            app.networkNodeStatus();
        end
        
        function results = checkAccDepth(~,tiltdepth)
            % depth, bits 0-4,  must be in range of 0-20
            % tilt, bit 7, is either 0 or 1
            low=uint8(31); %depth bits
            high=uint8(128); %tilt bit
            tilt=bitand(tiltdepth,high); % tilt
            depth=bitand(tiltdepth,low); % depth
            if depth > 20
                depth=20; % limit range
            end
            results=bitor(tilt,depth);
        end


        function networkNodeStatus(app)
            %read network status at 3004.1
            resp=app.nnp.read(7,'3004',1,'uint8');
            if length(resp)==1
                if resp==1
                    app.NetworkLamp.Color=[0,1,0];
                else
                    app.NetworkLamp.Color=[.9,.9,.9];
                end
            else
                app.NetworkLamp.Color=[.9,.9,.9];
                app.TopMessage('Could not read network status');
            end
            %read mode status at 2010.1 byte 2
            resp=app.nnp.read(7,'2010',1,'uint32');
            if length(resp)==1
                modebytes=typecast(resp,'uint8'); % get the bytes
                mode=modebytes(2); %just need byte 2 for mode
                if mode==5 %patient mode
                    app.PatientLamp.Color=[0,0,1];
                else
                    app.PatientLamp.Color=[.9,.9,.9];
                end
            else
                app.PatientLamp.Color=[.9,.9,.9];
                app.TopMessage('Could not read PM mode status');
            end
        end
        
        function OpenFileByName(app,filename,filepath)
            %here
            if filename == 0
                return
            else
                app.auxfilename = fullfile(filepath, filename);
                app.ParamFileEditField.Value=app.auxfilename;
            end
            
            in=load(app.auxfilename);
            if ~isfield(in, 'Aux1Par') % this file does not contain the right structure
                % here I could test also test the number of fields in rmParam
                %msgbox('Aux1 Parameter  mat file must contain the structure variable named "Aux1Par". Please try again.');
                uialert(app.UIFigure,'Aux1 Parameter  mat file must contain the structure variable named "Aux1Par". Please try again.');
            else
                
                app.Aux1Par=in.Aux1Par; %copy to app
                app.MESNodeEditField.Value=in.bpNode;
                app.StimNodesEditField.Value=num2str(in.pgNodeList);
                app.UpdateAuxVariables();
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin<2
                NNP=[];
            else
                NNP=varargin{1};
            end
             useNNP=true;
            if isempty(NNP)  || ~isvalid(NNP) %if being called after nnp cancelled from another app
                app.nnp = NNPAPI();
                if isempty(app.nnp.port)
    %                 msgbox('No port chosen for NNP Access Point')
                     uialert(app.UIFigure,'No port chosen for NNP Access Point','No NNP AP')
                    delete(app.nnp)
                    figure(app.UIFigure); %return focus
                     useNNP=false;
    %                 return
                end
            else
                app.nnp = NNP;
            end
           
            if nargin>2
                app.callingFigure=varargin{2};
            end
            
            if nargin>3  % third is userFolders
                app.userFolders=varargin{3};  % userfiles, code, user, config, data, log
                app.UserIDEditField.Value=app.userFolders.code;
            end

            % Note: In order to make the selected value a number rather than string, you have to asigne it at startup,
            % otherwise they will always be strings (a bug I think).   So here I am assigning it to be and index and
            % mapping that index to an array of pointers in the array for the index of the acc pdo values
            if useNNP
                app.networkNodeStatus();
                
                app.NodeDisplayDropDown.ItemsData=double([1:7])'; % to make this anything other than cell array, need to enter manually
                % changing the ItemsData resets the default to the first index, so I need to set the default again here
                app.NodeDisplayDropDown.Value=4; % there wasn't an easy way to read the default index, other than searching the
                %             app.rmParam.bpSyncInt=1;
                %             app.rmParam.pgNodeList=str2num(app.StimNodesEditField.Value);
                %             app.rmParam.bpNode=app.MESNodeEditField.Value; % default node is 9
                
                % default Tpdo data
                app.defaultTpdoTable();
                
                % acc timer function
                app.accTimer=timer();
                app.accTimer.Period=0.1;
                app.accTimer.ExecutionMode='fixedRate';
                app.accTimer.BusyMode='queue';
                app.accTimer.TimerFcn=@app.updateAcc;
                
                %default buffers
                %            app.plotbufx=nan(app.accsamp,1); %default x buffer, 50 samples, 5 seconds
                %            app.plotbufy=nan(app.accsamp,1); %default y buffer, 50 samples, 5 seconds
                %            app.plotbufz=nan(app.accsamp,1); %default z buffer, 50 samples, 5 seconds
                
                app.plotbufx=zeros(app.accsamp,1)+138; %default x buffer, 50 samples, 5 seconds
                app.plotbufy=zeros(app.accsamp,1)+128; %default y buffer, 50 samples, 5 seconds
                app.plotbufz=zeros(app.accsamp,1)+118; %default z buffer, 50 samples, 5 seconds
                app.plotbufc=zeros(app.accsamp,1)+138; %default c buffer, 50 samples, 5 seconds
                
                % first set of plots
                % three plots on same graph
                app.StatusButtonPushed();
                app.px=plot(app.AccAxes,1:app.accsamp,app.plotbufx,"Color",[1,0,0]);
                hold(app.AccAxes,'on');
                app.py=plot(app.AccAxes,1:app.accsamp,app.plotbufy,"Color",[0,1,0]);
                %          hold(app.AccAxes);
                app.pz=plot(app.AccAxes,1:app.accsamp,app.plotbufz,"Color",[0,0,1]);
                %          app.lgd=legend(app.AccAxes,'X','Y','Z','Location','northoutside');  % skip because too much space
                app.AccAxes.Title.String=app.NodeDisplayDropDown.Items(app.NodeDisplayDropDown.Value); % I think this will work?
                app.AccAxes.YLimMode='manual';
                app.AccAxes.YLim=[-1,256];
                % display gain and offset
                y1v=app.OffsetEditField.Value;
                app.y1=yline(app.AccAxes,y1v,'-.','Color',[0.5,0.5,0.5]);
                y2v=y1v+255/app.GainEditField.Value;
                app.y2=yline(app.AccAxes,y2v,'-.','Color',[0.5,0.5,0.5]);
                %display command
                newcolor=[0,0,0];
                app.comIdx=str2num(app.ChanDropDown.Value)+1;
                newcolor(app.comIdx)=1;
                app.pc=plot(app.CommandAxes,1:app.accsamp,app.plotbufc,"Color",newcolor); % should match the index
                app.CommandAxes.YLimMode='manual';
                app.CommandAxes.YLim=[-5,260];
                app.logfolder=pwd; %start with current folder
            end
            if isfield(app.userFolders,'config')&& ~isempty(app.userFolders.config)
               filename=[app.userFolders.code,app.defaultSuffix];
               app.defaultName=filename; % this is reduntant, could combine above
               filepath=app.userFolders.config;
               fullName=fullfile(filepath,filename);
               if exist(fullName,'file')==2  % make sure file exists before trying to open
                   app.OpenFileByName(filename,filepath);
               end
           end
        end

        % Callback function
        function ReadBPSettingsButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.BPTab;
            app.getBPdata();
        end

        % Button pushed function: OffButton
        function OffButtonPushed(app, event)
            app.nnp.enterWaiting();   % waiting mode
            success=app.nnp.networkOff();  % network off
            if success %true if successful
                app.NetworkLamp.Color=[0.90,0.90,0.90];
                app.PatientLamp.Color=[0.90,0.90,0.90];
            else
                %need message
            end
        end

        % Callback function
        function WriteBPButtonPushed(app, event)
            %    app.Write2BP();
        end

        % Button pushed function: ClearMessageButton
        function ClearMessageButtonPushed(app, event)
            app.ClearMessages();
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            %here
            % open mat file to read in remote module data
            % start with a mat file that I create externally to see if I have all the necessary variables saved.
            
            if app.checkDoSave()
                return
            end
            if isfield(app.userFolders,'config')&& ~isempty(app.userFolders.config)
                    fname=[app.userFolders.code,app.defaultSuffix];
                    defName=fullfile(app.userFolders.config,fname); % name with path                  
            else
                defName=app.defaultName; % name without path
            end
			[filename, filepath] = uigetfile('.mat', 'Open Aux1 parameters mat File',defName); % Exercise parameter file

            if filename
                app.OpenFileByName(filename,filepath);
            end
            %{
            [filename, pathname ] = uigetfile('*.mat', 'Open Aux1 parameters mat File');
            if filename == 0
                return
            else
                app.auxfilename = [pathname filename];
                app.ParamFileEditField.Value=app.auxfilename;
            end
            
            in=load(app.auxfilename);
            if ~isfield(in, 'Aux1Par') % this file does not contain the right structure
                % here I could test also test the number of fields in rmParam
                msgbox('Aux1 Parameter  mat file must contain the structure variable named "Aux1Par". Please try again.');
            else
                
                app.Aux1Par=in.Aux1Par; %copy to app
                app.MESNodeEditField.Value=in.bpNode;
                app.StimNodesEditField.Value=num2str(in.pgNodeList);
                app.UpdateAuxVariables();
            end
            %}
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
           %here
            % get a file name and save the data to that file
            % include a default file name.  How about rmParameters.mat
            defName=fullfile(app.userFolders.config,app.defaultName);
            [filename, pathname ] = uiputfile('*.mat', 'Save As Aux1 parameters mat File',defName);
            if filename == 0
                return
            else
                app.auxfilename = fullfile(pathname, filename);
                SaveMenuSelected(app, event);
                app.pathname=pathname;
                app.ParamFileEditField.Value=app.auxfilename;
            end
            
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            %check for valid file name.   get new name if not valid
            % save data to file
            if isempty(app.auxfilename)
                SaveAsMenuSelected(app, event);
            else
                app.FillAux1Par(); % fill the parameter array before writing
                bpNode=app.MESNodeEditField.Value;
                pgNodeList=str2num(app.StimNodesEditField.Value);
                Aux1Par=app.Aux1Par; % save as local variable to save
                save(app.auxfilename,'Aux1Par','bpNode','pgNodeList'); % save the whole structure as a variable, rather than individual
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.checkDoSave()
                return
            end
            % if I add rm help, here is where I would close those handles
            if ishandle(app.callingFigure) % check that is is valid
                figure(app.callingFigure); % bring to front
            end
            
            delete(app)
            
        end

        % Button pushed function: OnButton
        function OnButtonPushed(app, event)
            app.nnp.enterWaiting();   % waiting mode
            success=app.nnp.networkOn();  % network off
            if success %true if successful
                app.NetworkLamp.Color=[0.0,0.90,0.00];
            else
                %need message
            end
        end

        % Cell edit callback: TpdoActiveTable
        function TpdoActiveTableCellEdit(app, event)
            indices = event.Indices; % row/column of Data
            % if column=2, enable/disable
            % if column=3, set depth
            newData = event.NewData;
            node=app.TpdoActiveTable.Data{indices(1),1}; %this is true of either case
            if indices(2)==2 % enable/disable
                dmessage=sprintf('PDO for node %d',node);
                if newData(1) %select active
                    send=1;
                    dm='Enable ';
                else % select inactive
                    send=253;
                    dm='Disable ';
                end
                
                resp=app.nnp.write(node,'1800',2,send,'uint8');
                if isequal(resp, 0)
                    app.TopMessage([dm,dmessage]);
                    app.nnp.nmt(node, '0a');
                else
                    app.TopMessage(['Failed -',dm,dmessage]);
                end
            end
            if indices(2)==3 % set depth
                newDepth=app.checkAccDepth(uint8(newData(1))); % constrain new input to allowable values
                % write new depth back to table
                app.TpdoActiveTable.Data{indices(1),3}=double(newDepth); % I think this will write back correctly
                dmessage=sprintf('Depth %d for node %d',newDepth, node);
                % Here I need to decide how to handle Tilt option, which is bit 7.  For now assume it is 0 and ignore.
                % Later I will need a separate input that would add 128 if tilt is true
                resp=app.nnp.write(node,'2012',1,newDepth,'uint8');
                if isequal(resp, 0)
                    app.TopMessage(dmessage);
                    accMapCurrent=app.nnp.read(node,'1A00',1,'uint32'); % want this in hex format
                    pause(1); % allow read to finish
                    if length(accMapCurrent)==1
                        if newDepth>0 % need filtered data
                            accMap=uint32(537985568); % filtered uint32(hex2dec('20110220'))
                        else
                            accMap=uint32(537985312); % raw uint32(hex2dec('20110120'))
                        end
                        if accMapCurrent~=accMap
                            resp2=app.nnp.write(node, '1A00', 1,accMap, 'uint32');
                            dmessage=sprintf('tPDO for node %d', node);
                            if isequal(resp2, 0)
                                app.TopMessage(dmessage);
                            else
                                app.TopMessage(['Failed -',dmessage]);
                            end
                        else
                            app.TopMessage('PDO setting already correct');
                        end
                    else
                        app.TopMessage('could not read PDO setting');
                    end
                    resp=app.nnp.nmt(node, '0a');
                    pause(.5); % allow save to finish
                    if resp~=hex2dec('0A')
                        app.TopMessage('Error saving OD');
                    else
                        app.TopMessage('OD saved');
                    end
                    
                else
                    app.TopMessage(['Failed -',dmessage]);
                end
                % After setting the depth, I need to ensure that the tpdo
                % is using the correct index.
                % if the depth=0, then 2011.2 is disabled, so I need to use
                % 2011.1 in the tpdo.
                % if depth=1, then it is essentially the same as raw, but
                % additional overhead adds delay
           end
            % update the table
            app.TpdoTable.Active(node)=newData;
        end

        % Button pushed function: StatusButton
        function StatusButtonPushed(app, event)
            % read what is currently in the RMs
            % get the nodes
            % read status of network
            app.nnp.enterWaiting();
            app.PatientLamp.Color=[0.9 0.9 0.9];
            success=app.nnp.networkOn();  % network on
            pause(1); % wait for network to start
            if success %true if successful
                app.NetworkLamp.Color=[0 1 0];
            end
            nodes= app.TpdoTable.Node;
            nPG=size(nodes,1);
            dm=''; %start with blank message
            for i=1:nPG
                % get PDO transmission type
                resp=app.nnp.read(nodes(i),'1800',2,'uint8');
                if length(resp)==1
                    if resp==1
                        app.TpdoTable.Active(i)=true;
                    else
                        app.TpdoTable.Active(i)=false;
                    end
                    % app.TopMessage(num2str(nodes(i)));
                    dm=strcat(dm,num2str(nodes(i)),', ');
                else
                    % app.TopMessage(strcat('x-',num2str(nodes(i))));
                    dm=strcat(dm,'x-',num2str(nodes(i)),', ');
                end
                % get filter depth
                resp=app.nnp.read(nodes(i),'2012',1,'uint8');
                if length(resp)==1
                    app.TpdoTable.Depth(i)=resp;
                    %  app.TopMessage(num2str(nodes(i)));
                    dm=strcat(dm,num2str(nodes(i)),', ');
                else
                    %  app.TopMessage(strcat('x-',num2str(nodes(i))));
                    dm=strcat(dm,'x-',num2str(nodes(i)),', ');
                end
            end
            app.TopMessage(dm);
            % update the table
            app.TpdoActiveTable.Data=app.TpdoTable;
            %read the list of nodes
            %           if length(resp)==1
        end

        % Value changed function: ViewAccButton
        function ViewAccButtonValueChanged(app, event)
            value = app.ViewAccButton.Value;
            if value
                %read mode status at 2010.1 byte 2
                resp=app.nnp.read(7,'2010',1,'uint32');
                if length(resp)==1
                    modebytes=typecast(resp,'uint8'); % get the bytes
                    mode=modebytes(2); %just need byte 2 for mode
                    if mode==5 %patient mode
                        app.PatientLamp.Color=[0,0,1];
                        app.startAcc();
                        
                    else
                        app.PatientLamp.Color=[.9,.9,.9];
                        app.TopMessage('PM not in Patient mode');
                        f = msgbox('PM not in Patient mode.  Turn on PDOs and try again','Warning');
                        app.ViewAccButton.Value = false; %reset the button
                    end
                else
                    app.PatientLamp.Color=[.9,.9,.9];
                    app.TopMessage('Could not read PM mode status');
                    f = msgbox('Could not read PM status.  Check connections and try again','Warning');
                end
                
            else
                app.stopAcc();
            end
        end

        % Button pushed function: StartPDOsButton
        function StartPDOsButtonPushed(app, event)
            % here I could check the status first to see if network on and patient mode
            app.nnp.enterWaiting();   % waiting mode
            success=app.nnp.networkOn();  % network on
            if success %true if successful
                app.NetworkLamp.Color=[0.0,0.90,0.00];
                app.TopMessage('Network Started');
            else
                app.TopMessage('Failed Network Started');
            end
            pause(1); % wait for network to start before putting in patient mode
            success=app.nnp.enterPatient();
            if success %true if successful
                app.PatientLamp.Color=[0.0,0.00,0.90];
                app.TopMessage('Patient Mode Started');
            else
                app.TopMessage('Failed Patient Mode Started');
            end
        end

        % Button pushed function: WaitButton
        function WaitButtonPushed(app, event)
            success=app.nnp.enterWaiting();   % waiting mode
            if success %true if successful
                app.PatientLamp.Color=[0.90,0.90,0.90];
                app.TopMessage('Waiting Mode');
            else
                app.TopMessage('Failed Waiting Mode');
            end
        end

        % Value changed function: NodeDisplayDropDown
        function NodeDisplayDropDownValueChanged(app, event)
            value = app.NodeDisplayDropDown.Value;
            app.AccAxes.Title.String=app.NodeDisplayDropDown.Items(value);
        end

        % Button pushed function: WriteAuxButton
        function WriteAuxButtonPushed(app, event)
            %app.Aux1Par nine elements
            % 1 Aux1 Control Method
            % 2 Aux Delay Cycles
            % 3 Aux ramp up
            % 4 Aux ramp down
            % 5 Aux1 Acc Chan Subindex
            % 6 Aux1 Acc Orientation
            % 7 Aux Acc Offset
            % 8 Aux Acc Gain
            % 9 Aux1 Acc Filter Depth
            app.FillAux1Par();
            % write to PM
            result = app.nnp.write(7, '1F57',110 , uint8(app.Aux1Par), 9,'uint8');
            if ~isequal(result, 0)
                app.TopMessage('Error writing  to PM')
            else
                app.TopMessage('Aux1Par written and saved to PM')
                app.nnp.nmt(7, '0a');
            end
            
        end

        % Button pushed function: ReadAuxButton
        function ReadAuxButtonPushed(app, event)
            result = app.nnp.read(7, '1F57',110 , 9,'uint8');
            if length(result)==9
                app.TopMessage('Aux1Par from PM')
                app.Aux1Par=double(result);
                % The rest should be put into separate function, so I can call it when reading file.
                app.UpdateAuxVariables();
                %app.Aux1Par nine elements
                % 1 Aux1 Control Method
                % 2 Aux Delay Cycles
                % 3 Aux ramp up
                % 4 Aux ramp down
                % 5 Aux1 Acc Chan Subindex
                % 6 Aux1 Acc Orientation
                % 7 Aux Acc Offset
                % 8 Aux Acc Gain
                % 9 Aux1 Acc Filter Depth
                
            else
                app.TopMessage('Error reading from PM')
            end
            
        end

        % Value changed function: ChanDropDown
        function ChanDropDownValueChanged(app, event)
            value = app.ChanDropDown.Value;
            app.comIdx=str2num(value)+1;
            newcolor=[0,0,0];
            newcolor(app.comIdx)=1;
            app.pc.Color=newcolor; % should match the index
        end

        % Value changed function: OffsetEditField
        function OffsetEditFieldValueChanged(app, event)
            app.updateYlines();
        end

        % Value changed function: GainEditField
        function GainEditFieldValueChanged(app, event)
            app.updateYlines();
        end

        % Value changed function: LogOnButton
        function LogOnButtonValueChanged(app, event)
            value = app.LogOnButton.Value;
            if value
                app.LogOnButton.Text='Log On';
                app.LogOnButton.BackgroundColor=[0,1,0];
                % if log is on chack for valid log file ID and open if necessary
                app.getLogfilename();
            else
                app.LogOnButton.Text='Log Off';
                app.LogOnButton.BackgroundColor=[0.9,0.9,0.9];
            end
        end

        % Value changed function: SourceSwitch
        function SourceSwitchValueChanged(app, event)
            value = app.SourceSwitch.Value;
            
        end

        % Button pushed function: Aux1ScriptOnlyNoStimButton
        function Aux1ScriptOnlyNoStimButtonPushed(app, event)
            app.AuxOnlyScript();
            
        end

        % Button pushed function: RestartNormalScriptsButton
        function RestartNormalScriptsButtonPushed(app, event)
            app.resetScripts();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Accelerometer Settings';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.OpenMenu = uimenu(app.Menu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.Menu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.Menu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Text = 'SaveAs';
            app.LogMenu = uimenu(app.UIFigure);
            app.LogMenu.Text = 'Log';
            app.NewFileMenu = uimenu(app.LogMenu);
            app.NewFileMenu.Text = 'New File';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PGTab = uitab(app.TabGroup);
            app.PGTab.Title = 'PG';
            app.AccAxes = uiaxes(app.PGTab);
            title(app.AccAxes, 'Acc')
            xlabel(app.AccAxes, 'Samples')
            ylabel(app.AccAxes, 'Acc')
            app.CommandAxes = uiaxes(app.PGTab);
            title(app.CommandAxes, 'Command')
            xlabel(app.CommandAxes, 'Samples')
            ylabel(app.CommandAxes, 'Command')
            app.CommandAxes.YTick = [0 50 128 200 255];
            app.CommandAxes.YTickLabel = {'0'; '50'; '128'; '200'; '255'};
            app.TpdoActiveTable = uitable(app.PGTab);
            app.TpdoActiveTable.ColumnName = {'Node'; 'Active'; 'Depth'};
            app.TpdoActiveTable.RowName = {};
            app.TpdoActiveTable.ColumnEditable = [false true true];
            app.TpdoActiveTable.CellEditCallback = createCallbackFcn(app, @TpdoActiveTableCellEdit, true);
            app.TpdoActiveTable.Tooltip = {'If filter depth is greater than 1, the tpdo setting for that node needs to be set to 2011.2 instead of 2011.1'};
            app.StatusButton = uibutton(app.PGTab, 'push');
            app.StatusButton.ButtonPushedFcn = createCallbackFcn(app, @StatusButtonPushed, true);
            app.StatusButton.Text = 'Status';
            app.NodeDisplayDropDown = uidropdown(app.PGTab);
            app.NodeDisplayDropDown.Items = {'Node 9', 'Node 1', 'Node 2', 'Node 3', 'Node 4', 'Node 5', 'Node 6'};
            app.NodeDisplayDropDown.ItemsData = {'1', '5', '9', '13', '17', '21', '25', ''};
            app.NodeDisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @NodeDisplayDropDownValueChanged, true);
            app.NodeDisplayDropDown.Value = '13';
            app.ViewAccButton = uibutton(app.PGTab, 'state');
            app.ViewAccButton.ValueChangedFcn = createCallbackFcn(app, @ViewAccButtonValueChanged, true);
            app.ViewAccButton.Text = 'View Acc';
            app.SourceSwitchLabel = uilabel(app.PGTab);
            app.SourceSwitchLabel.Text = 'Source';
            app.SourceSwitch = uiswitch(app.PGTab, 'slider');
            app.SourceSwitch.Items = {'Calc', 'PM'};
            app.SourceSwitch.ValueChangedFcn = createCallbackFcn(app, @SourceSwitchValueChanged, true);
            app.SourceSwitch.Value = 'Calc';
            app.XLabel = uilabel(app.PGTab);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.PGTab);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.PGTab);
            app.ZLabel.Text = 'Z';
            app.LogOnButton = uibutton(app.PGTab, 'state');
            app.LogOnButton.ValueChangedFcn = createCallbackFcn(app, @LogOnButtonValueChanged, true);
            app.LogOnButton.Text = 'Log Off';
            app.plotOnCycleEditFieldLabel = uilabel(app.PGTab);
            app.plotOnCycleEditFieldLabel.Text = 'plotOnCycle';
            app.plotOnCycleEditField = uieditfield(app.PGTab, 'numeric');
            app.plotOnCycleEditField.Value = 2;
            app.NodeActiveDepthLabel = uilabel(app.PGTab);
            app.NodeActiveDepthLabel.Text = 'Node  Active  Depth';
            app.AuxParTab = uitab(app.TabGroup);
            app.AuxParTab.Title = 'AuxPar';
            app.BPTab = uitab(app.TabGroup);
            app.BPTab.Title = 'BP';
            app.SyncIntEditFieldLabel = uilabel(app.BPTab);
            app.SyncIntEditFieldLabel.Text = 'Sync Int';
            app.SyncIntEditField = uieditfield(app.BPTab, 'numeric');
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.MESNodeEditFieldLabel = uilabel(app.Panel);
            app.MESNodeEditFieldLabel.Text = 'MES Node';
            app.MESNodeEditField = uieditfield(app.Panel, 'numeric');
            app.MESNodeEditField.Value = 9;
            app.StimNodesEditFieldLabel = uilabel(app.Panel);
            app.StimNodesEditFieldLabel.Text = 'Stim Nodes';
            app.StimNodesEditField = uieditfield(app.Panel, 'text');
            app.StimNodesEditField.Value = '1 2 3 4 5 6';
            app.Messages = uitextarea(app.Panel);
            app.ClearMessageButton = uibutton(app.Panel, 'push');
            app.ClearMessageButton.ButtonPushedFcn = createCallbackFcn(app, @ClearMessageButtonPushed, true);
            app.ClearMessageButton.Text = 'clear';
            app.NetworkPanel = uipanel(app.Panel);
            app.NetworkPanel.Title = 'Network';
            app.OnButton = uibutton(app.NetworkPanel, 'push');
            app.OnButton.ButtonPushedFcn = createCallbackFcn(app, @OnButtonPushed, true);
            app.OnButton.Text = 'On';
            app.OffButton = uibutton(app.NetworkPanel, 'push');
            app.OffButton.ButtonPushedFcn = createCallbackFcn(app, @OffButtonPushed, true);
            app.OffButton.Text = 'Off';
            app.NetworkLamp = uilamp(app.NetworkPanel);
            app.StartPDOsButton = uibutton(app.NetworkPanel, 'push');
            app.StartPDOsButton.ButtonPushedFcn = createCallbackFcn(app, @StartPDOsButtonPushed, true);
            app.StartPDOsButton.Text = {'Start'; 'PDOs'};
            app.PatientLamp = uilamp(app.NetworkPanel);
            app.WaitButton = uibutton(app.NetworkPanel, 'push');
            app.WaitButton.ButtonPushedFcn = createCallbackFcn(app, @WaitButtonPushed, true);
            app.WaitButton.Text = 'Wait';
            app.UserEditField = uieditfield(app.Panel, 'numeric');
            app.UserEditField.ValueDisplayFormat = '%d';
            app.UserEditField.Value = 100;
            app.ParticipantLabel = uilabel(app.Panel);
            app.ParticipantLabel.Text = 'Participant';
            app.UserIDEditField = uieditfield(app.Panel, 'text');
            app.UserIDEditField.Editable = 'off';
            app.ParamFileEditFieldLabel = uilabel(app.UIFigure);
            app.ParamFileEditFieldLabel.Text = 'Param File';
            app.ParamFileEditField = uieditfield(app.UIFigure, 'text');
            app.Aux1Panel = uipanel(app.UIFigure);
            app.Aux1Panel.Title = 'Aux1';
            app.DelayEditFieldLabel = uilabel(app.Aux1Panel);
            app.DelayEditFieldLabel.Text = 'Delay';
            app.DelayEditField = uieditfield(app.Aux1Panel, 'numeric');
            app.MethodDropDownLabel = uilabel(app.Aux1Panel);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.Aux1Panel);
            app.MethodDropDown.Items = {'Ramp', 'Acc'};
            app.MethodDropDown.ItemsData = {'0', '1'};
            app.MethodDropDown.Value = '0';
            app.RampUpEditFieldLabel = uilabel(app.Aux1Panel);
            app.RampUpEditFieldLabel.Text = 'Ramp Up';
            app.RampUpEditField = uieditfield(app.Aux1Panel, 'numeric');
            app.RampUpEditField.Value = 26;
            app.RampDownEditFieldLabel = uilabel(app.Aux1Panel);
            app.RampDownEditFieldLabel.Text = 'Ramp Down';
            app.RampDownEditField = uieditfield(app.Aux1Panel, 'numeric');
            app.RampDownEditField.Value = 13;
            app.ChanDropDownLabel = uilabel(app.Aux1Panel);
            app.ChanDropDownLabel.Text = 'Chan';
            app.ChanDropDown = uidropdown(app.Aux1Panel);
            app.ChanDropDown.Items = {'X', 'Y', 'Z'};
            app.ChanDropDown.ItemsData = {'0', '1', '2'};
            app.ChanDropDown.ValueChangedFcn = createCallbackFcn(app, @ChanDropDownValueChanged, true);
            app.ChanDropDown.Value = '1';
            app.OffsetEditFieldLabel = uilabel(app.Aux1Panel);
            app.OffsetEditFieldLabel.Text = 'Offset';
            app.OffsetEditField = uieditfield(app.Aux1Panel, 'numeric');
            app.OffsetEditField.ValueChangedFcn = createCallbackFcn(app, @OffsetEditFieldValueChanged, true);
            app.OffsetEditField.Value = 128;
            app.GainEditFieldLabel = uilabel(app.Aux1Panel);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.Aux1Panel, 'numeric');
            app.GainEditField.ValueChangedFcn = createCallbackFcn(app, @GainEditFieldValueChanged, true);
            app.GainEditField.Value = 20;
            app.FilterDepthEditFieldLabel = uilabel(app.Aux1Panel);
            app.FilterDepthEditFieldLabel.Visible = 'off';
            app.FilterDepthEditFieldLabel.Text = 'Filter Depth';
            app.FilterDepthEditField = uieditfield(app.Aux1Panel, 'numeric');
            app.FilterDepthEditField.Editable = 'off';
            app.FilterDepthEditField.Visible = 'off';
            app.FilterDepthEditField.Value = 5;
            app.DirDropDownLabel = uilabel(app.Aux1Panel);
            app.DirDropDownLabel.Text = 'Dir';
            app.DirDropDown = uidropdown(app.Aux1Panel);
            app.DirDropDown.Items = {'Positive', 'Negative'};
            app.DirDropDown.ItemsData = {'0', '1'};
            app.DirDropDown.Value = '0';
            app.NodeDropDownLabel = uilabel(app.Aux1Panel);
            app.NodeDropDownLabel.Text = 'Node';
            app.AccNodeDropDown = uidropdown(app.Aux1Panel);
            app.AccNodeDropDown.Items = {'1', '2', '3', '4', '5', '6', '9'};
            app.AccNodeDropDown.ItemsData = {'168', '172', '176', '180', '184', '188', '164'};
            app.AccNodeDropDown.Value = '176';
            app.ReadAuxButton = uibutton(app.Aux1Panel, 'push');
            app.ReadAuxButton.ButtonPushedFcn = createCallbackFcn(app, @ReadAuxButtonPushed, true);
            app.ReadAuxButton.Text = 'Read PM';
            app.WriteAuxButton = uibutton(app.Aux1Panel, 'push');
            app.WriteAuxButton.ButtonPushedFcn = createCallbackFcn(app, @WriteAuxButtonPushed, true);
            app.WriteAuxButton.Text = 'Write PM';
            app.LogFileEditFieldLabel = uilabel(app.UIFigure);
            app.LogFileEditFieldLabel.Text = 'Log File';
            app.LogFileEditField = uieditfield(app.UIFigure, 'text');
            app.Aux1ScriptOnlyNoStimButton = uibutton(app.UIFigure, 'push');
            app.Aux1ScriptOnlyNoStimButton.ButtonPushedFcn = createCallbackFcn(app, @Aux1ScriptOnlyNoStimButtonPushed, true);
            app.Aux1ScriptOnlyNoStimButton.Tooltip = {'Stop other scripts, start correct scripts to monitor command'};
            app.Aux1ScriptOnlyNoStimButton.Text = {'Aux1 Script Only'; 'No Stim'};
            app.RestartNormalScriptsButton = uibutton(app.UIFigure, 'push');
            app.RestartNormalScriptsButton.ButtonPushedFcn = createCallbackFcn(app, @RestartNormalScriptsButtonPushed, true);
            app.RestartNormalScriptsButton.Text = {'Restart '; 'Normal Scripts'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = accSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end