classdef actionPatternMap < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        OpenMenu                     matlab.ui.container.Menu
        LoadgroupsfromMATfileMenu    matlab.ui.container.Menu
        LoadgroupsfromNNPSfileMenu   matlab.ui.container.Menu
        LoadActionMapandgroupsMenu   matlab.ui.container.Menu
        SaveMenu                     matlab.ui.container.Menu
        SaveActionMapwithgroupsMenu  matlab.ui.container.Menu
        PNGMenu                      matlab.ui.container.Menu
        UserIDEditField              matlab.ui.control.EditField
        Panel                        matlab.ui.container.Panel
        SaveODButton                 matlab.ui.control.Button
        ClearMessageButton           matlab.ui.control.Button
        MessagesLabel                matlab.ui.control.Label
        Messages                     matlab.ui.control.TextArea
        WritePMButton                matlab.ui.control.Button
        ReadPMButton                 matlab.ui.control.Button
        FunctionalGroupTable         matlab.ui.control.Table
        ActionMappingTable           matlab.ui.control.Table
        FunctionalGroupsinfileordownloadedLabel  matlab.ui.control.Label
        ActionPatterntoFunctionalGroupMappingLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
       actionPatternTable=[]; % Description
        bgColors=ones(32,3); % start with all zeros
        actionMap=zeros(32,1); % Description
        nnp=[];
        nnps=[]; % structure for groups, channels, nodes, patterns
        saveFile=[]; % Description
        pngFile=[]; % snapshot of action maps
        pdfFile=[]; % snapshot of action maps
        actionMapNames=cell(32,1); % Names associated with assigned functional groups
        ltYellow=[1,1,0.8];
        styleYellowBold=uistyle('BackgroundColor',[1,1,.8],'FontWeight','bold') % light yellow bold
        callingFigure=[]; %handle to calling figure
        % Code for auto file open
         userFolders=struct;  % userfiles, code, user, config, data, log
         defaultSuffix='_Action_Pattern_Map.mat';
         defaultName='P00_Action_Pattern_Map.mat';
    end
    
    methods (Access = public)
        
        function setBgColors(app)
            % set the colors of the table
            % rather than striping every other row, I want to group them.
            % Start with just two colors
            white=[1,1,1];
            ltBlue=[.8,1,1];
            % since they started all ones, only need to set every other group
            for i=[6:8,12:16,20:22,28:32]
                app.bgColors(i,:)=ltBlue;
            end
            app.ActionMappingTable.BackgroundColor=app.bgColors;
        end
        
               
        function ClearMessages(app)                   
           app.Messages.Value=''; % clear messages 
        end
                
        function TopMessage(app,message)
          app.Messages.Value=vertcat(message, string(app.Messages.Value)); % put new message on top  
        end
        
        
        function updateFGTables(app)
%            	groups	18x1 cell
%           		{'name'    }
%           		{'cmdID'   }
%           		{'download'}
%           		{'notes'   }
% What if the FG numbers are not contiguous?
            for i=1:size(app.nnps.groups,1)
                gpnum(i)=i;
                cmdID(i)=app.nnps.groups{i}.cmdID;
                name{i}=app.nnps.groups{i}.name;
                download(i)=app.nnps.groups{i}.download;
                notes{i}=app.nnps.groups{i}.notes;
                gpt=table(gpnum',cmdID',name',download',notes');
                app.FunctionalGroupTable.Data=gpt;                
            end
        end
        
        function fillLogTable(app)
          fgMax=size(app.nnps.groups,1);
          for i=1:32
              fg=app.actionMap(i);
              if fg>0 && fg<=fgMax
                  fgname=app.nnps.groups{fg}.name;
                  if ~isempty(fgname)
                      app.actionMapNames{i}=fgname;
                  else
                      app.actionMapNames{i}='none';
                  end
              else
                  app.actionMapNames{i}='none';
              end
          end
          app.ActionMappingTable.Data{:,4}=app.actionMap;
          app.ActionMappingTable.Data{:,5}=app.actionMapNames;
        end
        
        function defaultActionMap(app)
            %simplify this by adding columns
            % cmdID, Action, #, FG, Name
            cmdID=[1,1,1,1,1,2,2,2,3,3,3,4,4,4,4,4,5,5,5,6,6,6,7,7,7,7,7,8,8,8,8,8]';
            Action={'Hand','','','','',...
                'Aux1','','',...
                'Aux2','','',...
                'Trunk','','','','',...
                'Aux3','','',...
                'Aux4','','',...
                'Exer1','','','','',...
                'Exer2','','','',''}';
            LG=[1:5,1:3,1:3,1:5,1:3,1:3,1:5,1:5]';
            FG=zeros(32,1);
            Names=cell(32,1);
            for i=1:32
                Names{i}='none';
            end           
            app.actionPatternTable=table(cmdID,Action,LG,FG,Names);
            % app.ActionMappingTable.ColumnFormat={'numeric','numeric'}; %to right justify
            app.ActionMappingTable.Data=app.actionPatternTable;
            app.setBgColors();
        end
    end
    
    methods (Access = private)
        
        function setFGBgColors(app)
           fgRows=size(app.FunctionalGroupTable.Data,1);       
            bgColor=ones(fgRows,3); %start with all white
            app.FunctionalGroupTable.BackgroundColor=bgColor; 
        end
        
        function OpenFileByName(app,filename,filepath)
            if filename
                ld = load(fullfile(filepath, filename));
                TempFile = fullfile(filepath, filename);  % save filename as temporary until the file is checked
            else
                return;
            end
            % I could skip this for patterns
            fields = {'actionMap', 'groups'};
            iMissing = find(~isfield(ld, fields));

            str = [];
            for i=1:length(iMissing)
                str = [str ', ' fields{iMissing(i)}];
            end
            if ~isempty(str)
                warndlg(['Invalid Action Map File.  Does not contain variable(s): ' str '.'], 'Open File')
                return;
             end
             %  If the file is invalid, we don't want to update anything, including the file name
             app.saveFile = TempFile;   % the only file we need to save is ActionMap file
             [fp,fn,~]=fileparts(app.saveFile);
             app.pngFile=fullfile(fp,[fn,'.png']);
             app.pdfFile=fullfile(fp,[fn,'.pdf']);
             app.nnps.groups = ld.groups;
             app.actionMap=ld.actionMap;
             app.fillLogTable();
             app.updateFGTables();
             app.ReadPMButton.Enable='on';
             app.WritePMButton.Enable='on';
             figure(app.UIFigure); % return focus to this window after reading
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin<2
                NNP=[];
            else
                NNP=varargin{1};
            end
            if isempty(NNP)
                app.nnp = NNPAPI();
                if isempty(app.nnp.port)
                    %msgbox('No port chosen for NNP Access Point')
                    uialert(app.UIFigure,'No port chosen for NNP Access Point','No NNP AP')
                    delete(app.nnp)
                    %                     return
                end
            else
                app.nnp = NNP;
            end
            if nargin>2
                app.callingFigure=varargin{2};
            end
            if nargin>3  % third is userFolders
                app.userFolders=varargin{3};  % userfiles, code, user, config, data, log
                app.UserIDEditField.Value=app.userFolders.code;
            end
            app.defaultActionMap();
            addStyle(app.ActionMappingTable,app.styleYellowBold,'column',4); % first added so this will be 1 if I want to remove
            if isfield(app.userFolders,'config') && ~isempty(app.userFolders.config)
                filename=[app.userFolders.code,app.defaultSuffix];
                app.defaultName=filename; % this is reduntant, could combine above
                filepath=app.userFolders.config;
                fullName=fullfile(filepath,filename);
                if exist(fullName,'file')==2  % make sure file exists before trying to open
                    app.OpenFileByName(filename,filepath);
                end
            end
        end

        % Menu selected function: LoadgroupsfromMATfileMenu
        function LoadgroupsfromMATfileMenuSelected(app, event)
%             if  ~isempty(app.Patterns) || ~isempty(app.nnps) && (~isempty(app.nnps.channels) || ~isempty(app.nnps.nodes) || ~isempty(app.nnps.groups))
%                 resp = questdlg('You may have unsaved changes.  Do you want to continue with a new file?');
%                 if ~isequal(resp, 'Yes')
%                     return;
%                 end
%             end
            
            [filename, filepath] = uigetfile('.mat', 'Select NNP System File');
            if filename
                ld = load([filepath filename]);
                TempFile = [filepath filename];  % save filename as temporary until the file is checked
            
            else
                return;
            end
            % I could skip this for patterns
            fields = { 'channels', 'nodes', 'groups'};
            iMissing = find(~isfield(ld, fields));
            
            str = [];
            for i=1:length(iMissing)
                str = [str ', ' fields{iMissing(i)}];
            end
            if ~isempty(str)
                warndlg(['Invalid NNP System File.  Does not contain variable(s): ' str '.'], 'Open File')
                return;
            end
            app.nnps.nodes = ld.nodes;
            app.nnps.groups = ld.groups;
            app.updateFGTables();
            app.ReadPMButton.Enable='on';
            app.WritePMButton.Enable='on';
            figure(app.UIFigure); % return focus to this window after reading
        end

        % Menu selected function: LoadgroupsfromNNPSfileMenu
        function LoadgroupsfromNNPSfileMenuSelected(app, event)
            dmessage='loading from file';
            app.TopMessage(dmessage);
            [success, newchannels, newnodes, newgroups,~]=readnnpsCNGP();
            if success==1
                app.nnps.channels=newchannels;
                app.nnps.nodes=newnodes;
                app.nnps.groups=newgroups;
                app.TopMessage('success');
                app.updateFGTables(); 
                app.ReadPMButton.Enable='on';
                app.WritePMButton.Enable='on';
            else
                 app.TopMessage('fail');
            end
            figure(app.UIFigure); % return focus to this window after reading
        end

        % Button pushed function: ReadPMButton
        function ReadPMButtonPushed(app, event)
             new57=app.actionMap; % initialize with starting data
            % Read in three reads
            resp=app.nnp.read(7,'1F57',17,32,'uint8');
            if(length(resp)==32) %valid packet
                new57(1:32)=double(resp);
                app.actionMap=new57;
                dmessage='Read PM 1F57';
            else
                dmessage='error reading PM';
                return;
            end
            app.TopMessage(dmessage);
            % fill in the blanks
               app.fillLogTable();
        end

        % Button pushed function: WritePMButton
        function WritePMButtonPushed(app, event)
            result = app.nnp.write(7, '1F57', 17, uint8(app.ActionMappingTable.Data{:,4}'), 'uint8',32);
        if ~isequal(result, 0)
            app.TopMessage('Error Writing Action Patterns to PM')
        else
            app.TopMessage('Action Patterns to PM...');
            app.SaveODButton.BackgroundColor=[0.66,0.90,1.00]; %make the color blue to stand out
        end
        
        % after writing, I should change color of the save button until it
        % is pressed.
        
        end

        % Cell selection callback: ActionMappingTable
        function ActionMappingTableCellSelection(app, event)
            indices = event.Indices;
            row=indices(1);
            % read cmdID listed in LMT row
            LcmdID=app.ActionMappingTable.Data{row,1};
            fgRows=size(app.FunctionalGroupTable.Data,1);
            % for all rows, if cmdID==cmdID, make row white
            if LcmdID>6
            bgColor=ones(fgRows,3); %start with all white
            else
             bgColor=ones(fgRows,3)*.90; %start with all grey               
            end
            for i=1:fgRows
                if app.FunctionalGroupTable.Data{i,2}==LcmdID
                    bgColor(i,:)=app.ltYellow;
                end
            end
            app.FunctionalGroupTable.BackgroundColor=bgColor;
        end

        % Cell edit callback: ActionMappingTable
        function ActionMappingTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            % 
            if newData>0 && newData<=size(app.nnps.groups,1)
            newName=app.FunctionalGroupTable.Data{newData,3};
            app.ActionMappingTable.Data{indices(1),5}=newName;
            else
                newName={'none'};
                app.ActionMappingTable.Data{indices(1),5}=newName;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ishandle(app.callingFigure) % check that is is valid
                figure(app.callingFigure); % bring to front
            end
            delete(app)
 
        end

        % Menu selected function: LoadActionMapandgroupsMenu
        function LoadActionMapandgroupsMenuSelected(app, event)
            % load ActionMap groups variables from mat file

            if isfield(app.userFolders,'config')&& ~isempty(app.userFolders.config)
                    fname=[app.userFolders.code,app.defaultSuffix];
                    defName=fullfile(app.userFolders.config,fname); % name with path                  
            else
                defName=app.defaultName; % name without path
            end
			[filename, filepath] = uigetfile('.mat', 'Select file with Action map and groups',defName); 
          
            if filename
                app.OpenFileByName(filename,filepath);
            end
            %{
            if filename
                ld = load([filepath filename]);
                TempFile = [filepath filename];  % save filename as temporary until the file is checked

            else
                return;
            end
            % I could skip this for patterns
            fields = {'actionMap', 'groups'};
            iMissing = find(~isfield(ld, fields));

            str = [];
            for i=1:length(iMissing)
                str = [str ', ' fields{iMissing(i)}];
            end
            if ~isempty(str)
                warndlg(['Invalid Action Map File.  Does not contain variable(s): ' str '.'], 'Open File')
                return;
             end
             %  If the file is invalid, we don't want to update anything, including the file name
             app.saveFile = TempFile;   % the only file we need to save is ActionMap file
             [fp,fn,~]=fileparts(app.saveFile);
             app.pngFile=fullfile(fp,[fn,'.png']);
             app.pdfFile=fullfile(fp,[fn,'.pdf']);
             app.nnps.groups = ld.groups;
             app.actionMap=ld.actionMap;
             app.fillLogTable();
             app.updateFGTables();
             app.ReadPMButton.Enable='on';
             app.WritePMButton.Enable='on';
             figure(app.UIFigure); % return focus to this window after reading
            %}
        end

        % Menu selected function: SaveActionMapwithgroupsMenu
        function SaveActionMapwithgroupsMenuSelected(app, event)
            % Save actionMap and groups to file
            % could add separate SaveAs and Save callbacks
            if isfield(app.userFolders,'config')&& ~isempty(app.userFolders.config)
                    fname=[app.userFolders.code,app.defaultSuffix];
                    defName=fullfile(app.userFolders.config,fname); % name with path                  
            else
                defName=app.defaultName; % name without path
            end
			 [filename, filepath] = uiputfile('.mat', 'Select file to save action map and groups',defName);
            if filename
                saveFile=[filepath filename];
                groups=app.nnps.groups;
                app.actionMap=app.ActionMappingTable.Data{:,4};
                actionMap=app.actionMap;
                save(saveFile,'groups','actionMap'); % save as variables.  Use opened file as default for saving
                app.TopMessage('groups and actionMap saved to file');                
            else
                return;
            end
            figure(app.UIFigure); % return focus to this window after reading
        end

        % Callback function: FunctionalGroupTable
        function FunctionalGroupTableSelectionChanged(app, event)
            selection = app.FunctionalGroupTable.Selection;
             % selection will be a single integer because row is on and
            % multiselect is off
            amRow=app.ActionMappingTable.Selection;
            if ~isempty(amRow) % something selected
                fg=app.FunctionalGroupTable.Data(selection,1); %first column of selected row
                fgname=app.FunctionalGroupTable.Data(selection,3); %third column of selected row
                app.ActionMappingTable.Data(amRow,4)=fg;
                app.ActionMappingTable.Data(amRow,5)=fgname;
            end
            % deselect FG row after making update
            app.FunctionalGroupTable.Selection=[];
        end

        % Menu selected function: PNGMenu
        function PNGMenuSelected(app, event)
            if isempty(app.pngFile)
                [fn, fp] = uiputfile('.png', 'Location and name for PNG file');
                app.pngFile=[fp,fn];
                if file==0
                    return;
                end
            end
            app.ActionMappingTable.Selection=[];
            app.setFGBgColors();% should deselect rows to print better?
            exportapp(app.UIFigure,app.pngFile); % low res
            exportapp(app.UIFigure,app.pdfFile);

        end

        % Button pushed function: SaveODButton
        function SaveODButtonPushed(app, event)
            if app.nnp.saveOD(7)
                    dmessage = 'PM OD Saved';
                    % after saving, turn back to grey color
                    app.SaveODButton.BackgroundColor=[0.96,0.96,0.96]; % back to grey because saved
            else
                dmessage = 'Failed PM OD Save';
            end
            app.TopMessage(dmessage);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Action Pattern Map';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OpenMenu = uimenu(app.UIFigure);
            app.OpenMenu.Text = 'Open';
            app.LoadgroupsfromMATfileMenu = uimenu(app.OpenMenu);
            app.LoadgroupsfromMATfileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadgroupsfromMATfileMenuSelected, true);
            app.LoadgroupsfromMATfileMenu.Tooltip = {'System file, loads channels, nodes and groups but this window only uses groups'};
            app.LoadgroupsfromMATfileMenu.Text = 'Load groups from MAT file';
            app.LoadgroupsfromNNPSfileMenu = uimenu(app.OpenMenu);
            app.LoadgroupsfromNNPSfileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadgroupsfromNNPSfileMenuSelected, true);
            app.LoadgroupsfromNNPSfileMenu.Tooltip = {'Loads channels, nodes and groups but this window only uses groups'};
            app.LoadgroupsfromNNPSfileMenu.Text = 'Load groups from NNPS file';
            app.LoadActionMapandgroupsMenu = uimenu(app.OpenMenu);
            app.LoadActionMapandgroupsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadActionMapandgroupsMenuSelected, true);
            app.LoadActionMapandgroupsMenu.Text = 'Load Action Map and groups';
            app.SaveMenu = uimenu(app.UIFigure);
            app.SaveMenu.Text = 'Save';
            app.SaveActionMapwithgroupsMenu = uimenu(app.SaveMenu);
            app.SaveActionMapwithgroupsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveActionMapwithgroupsMenuSelected, true);
            app.SaveActionMapwithgroupsMenu.Text = 'Save actionMap with groups';
            app.PNGMenu = uimenu(app.UIFigure);
            app.PNGMenu.MenuSelectedFcn = createCallbackFcn(app, @PNGMenuSelected, true);
            app.PNGMenu.Text = 'PNG';
            app.ActionPatterntoFunctionalGroupMappingLabel = uilabel(app.UIFigure);
            app.ActionPatterntoFunctionalGroupMappingLabel.Text = 'Action Pattern to Functional Group Mapping';
            app.FunctionalGroupsinfileordownloadedLabel = uilabel(app.UIFigure);
            app.FunctionalGroupsinfileordownloadedLabel.Text = 'Functional Groups in file or downloaded';
            app.ActionMappingTable = uitable(app.UIFigure);
            app.ActionMappingTable.ColumnName = {'cmdID'; 'Action'; '#'; 'FG'; 'Name'};
            app.ActionMappingTable.RowName = {};
            app.ActionMappingTable.SelectionType = 'row';
            app.ActionMappingTable.ColumnEditable = [false false false true false];
            app.ActionMappingTable.CellEditCallback = createCallbackFcn(app, @ActionMappingTableCellEdit, true);
            app.ActionMappingTable.CellSelectionCallback = createCallbackFcn(app, @ActionMappingTableCellSelection, true);
            app.ActionMappingTable.Multiselect = 'off';
            app.FunctionalGroupTable = uitable(app.UIFigure);
            app.FunctionalGroupTable.ColumnName = {'Group'; 'Cmd ID'; 'Name'; 'Download'; 'Notes'};
            app.FunctionalGroupTable.RowName = {};
            app.FunctionalGroupTable.ColumnSortable = [true true false false false];
            app.FunctionalGroupTable.SelectionType = 'row';
            app.FunctionalGroupTable.SelectionChangedFcn = createCallbackFcn(app, @FunctionalGroupTableSelectionChanged, true);
            app.FunctionalGroupTable.Multiselect = 'off';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.ReadPMButton = uibutton(app.Panel, 'push');
            app.ReadPMButton.ButtonPushedFcn = createCallbackFcn(app, @ReadPMButtonPushed, true);
            app.ReadPMButton.Enable = 'off';
            app.ReadPMButton.Text = 'ReadPM';
            app.WritePMButton = uibutton(app.Panel, 'push');
            app.WritePMButton.ButtonPushedFcn = createCallbackFcn(app, @WritePMButtonPushed, true);
            app.WritePMButton.Enable = 'off';
            app.WritePMButton.Text = 'WritePM';
            app.Messages = uitextarea(app.Panel);
            app.MessagesLabel = uilabel(app.Panel);
            app.MessagesLabel.Text = 'Messages';
            app.ClearMessageButton = uibutton(app.Panel, 'push');
            app.ClearMessageButton.Text = 'clear';
            app.SaveODButton = uibutton(app.Panel, 'push');
            app.SaveODButton.ButtonPushedFcn = createCallbackFcn(app, @SaveODButtonPushed, true);
            app.SaveODButton.Text = 'Save OD';
            app.UserIDEditField = uieditfield(app.UIFigure, 'text');
            app.UserIDEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = actionPatternMap(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end