classdef backupRestoreMemory < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UserIDLabel                  matlab.ui.control.Label
        UserIDEditField              matlab.ui.control.EditField
        SelectRestoreButton          matlab.ui.control.StateButton
        SelectBackupButton           matlab.ui.control.StateButton
        ScriptBackupNameLabel        matlab.ui.control.Label
        ScriptBackupNameEditField    matlab.ui.control.EditField
        OtherHelpfulDataPanel        matlab.ui.container.Panel
        RemoteModuleCurrentConfigurationButton  matlab.ui.control.Button
        RestartScriptsPanel          matlab.ui.container.Panel
        NetworkOffButton             matlab.ui.control.Button
        WOROnButton                  matlab.ui.control.Button
        StartScriptsButton           matlab.ui.control.Button
        RestoreMemoryPanel           matlab.ui.container.Panel
        TurnonCoilfromCECTBEFORErestorescriptsLabel  matlab.ui.control.Label
        SetDateTimeButton            matlab.ui.control.Button
        RestoreScriptsButton         matlab.ui.control.Button
        RestoreRMButton              matlab.ui.control.Button
        RestorePMButton              matlab.ui.control.Button
        BackupMemoryPanel            matlab.ui.container.Panel
        TurnonCoilfromCECTBEFOREbackupscriptsLabel  matlab.ui.control.Label
        BackupScriptsButton          matlab.ui.control.Button
        BackupRMButton               matlab.ui.control.Button
        BackupPMButton               matlab.ui.control.Button
        PreparationPanel             matlab.ui.container.Panel
        NetworkOnButton              matlab.ui.control.Button
        RemoteModulesEditField       matlab.ui.control.EditField
        RemoteModulesLabel           matlab.ui.control.Label
        TestConnectionButton         matlab.ui.control.Button
        RadioConfigButton            matlab.ui.control.Button
        DisableScriptsButton         matlab.ui.control.Button
        WOROffButton                 matlab.ui.control.Button
        RMBackupNameLabel            matlab.ui.control.Label
        PMBackupNameLabel            matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        MessagesTextArea             matlab.ui.control.TextArea
        MessagesTextAreaLabel        matlab.ui.control.Label
        AssumethatyouhavealreadyrunnnpNNPAPIandpassednnptothisappLabel  matlab.ui.control.Label
        FolderEditField              matlab.ui.control.EditField
        FolderButton                 matlab.ui.control.Button
        BackupandRestoreMemoryLabel  matlab.ui.control.Label
        PMBackupNameEditField        matlab.ui.control.EditField
        RMBackupNameEditField        matlab.ui.control.EditField
    end

    
    properties (Access = public)
        nnp=[];
        selpath=pwd;% get the current working directory
        
        RFileID=[]; % File ID for Retore File
        LogFileName='TempLogFileName.bin'; % Save this to a temporary file
        LFileID=[]; % File ID for Log File
        PMBackupName='PowerModuleBackuptemp.mat'; % file name for Power Module
        RMBackupName='RemoteModuleMBackuptemp.mat'; % file name for Remote Modules
        ScriptBackupName='ScriptBackuptemp.mat'; % file name for Scripts
        PMfileID
       callingFigure=[]; %handle to calling figure
        % Code for auto userID open
         userFolders=struct;  % userfiles, code, user, config, data, log, where code is file prefix
         defaultPMBUSuffix='_PM_Backup'; %before all the date/time information is added
         defaultRMBUSuffix='_RM_Backup'; %before all the date/time information is added
         defaultScriptBUSuffix='_Script_Backup'; %before all the date/time information is added
         defaultPMBUName='P00_PM_Backup'; %if userFolders is not valid use this
         defaultRMBUName='P00_RML_Backup'; %if userFolders is not valid use this
         defaultScriptBUName='P00_Script_Backup'; %if userFolders is not valid use this
         defaultPath=pwd;
    end
    
    methods (Access = public)
        
        function TopMessage(app,message)
                app.MessagesTextArea.Value=vertcat(message, string(app.MessagesTextArea.Value)); % put new message on top
        end
        
        function FillFileNameFields(app)
            timestring=sprintf('_%02.0f',datevec(now));

            app.PMBackupNameEditField.Value=[app.defaultPMBUName,timestring,'.mat'];
            app.PMBackupName=fullfile(app.FolderEditField.Value,app.PMBackupNameEditField.Value);

            app.RMBackupNameEditField.Value=[app.defaultRMBUName,timestring,'.mat'];
            app.RMBackupName=fullfile(app.FolderEditField.Value,app.RMBackupNameEditField.Value);  

            app.ScriptBackupNameEditField.Value=[app.defaultScriptBUName,timestring,'.mat'];
            app.ScriptBackupName=fullfile(app.FolderEditField.Value,app.ScriptBackupNameEditField.Value);

           
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin<2
                NNP=[];
            else
                NNP=varargin{1};
            end
             useNNP=true;
            if isempty(NNP)  || ~isvalid(NNP) %if being called after nnp cancelled from another app
                app.nnp = NNPAPI();
                if isempty(app.nnp.port)
                    uialert(app.UIFigure,'No port chosen for NNP Access Point','No NNP AP')
                    delete(app.nnp)
                    useNNP=false;
%                     return
                end
            else
                app.nnp = NNP;
            end
            if nargin>2
                app.callingFigure=varargin{2};
            end
            if nargin>3  % third is userFolders
                app.userFolders=varargin{3};  % userfiles, code, user, config, data, log
                if isfield(app.userFolders,'code')&& ~isempty(app.userFolders.code)
                    app.UserIDEditField.Value=app.userFolders.code;
                end
                if isfield(app.userFolders,'log')&& ~isempty(app.userFolders.log)
                    app.defaultPMBUName=[app.userFolders.code,app.defaultPMBUSuffix]; % name without extension
                     app.defaultRMBUName=[app.userFolders.code,app.defaultRMBUSuffix]; % name without extension
                    app.defaultScriptBUName=[app.userFolders.code,app.defaultScriptBUSuffix]; % name without extension
                    app.defaultPath=app.userFolders.log;
                    app.FolderEditField.Value=app.defaultPath;
                    app.FillFileNameFields();
                end   
            end
%         PMBackupName='PowerModuleBackuptemp.mat'; % file name for Power Module
%         RMBackupName='RemoteModuleMBackuptemp.mat'; % file name for Remote Modules
%         ScriptBackupName='ScriptBackuptemp.mat'; % file name for Scripts
        end

        % Button pushed function: FolderButton
        function FolderButtonPushed(app, event)
            %             app.selpath = uigetdir(app.selpath,'Directory to store Log files');
            %             figure(app.UIFigure); % Restore focus to getPMLog window
            %             app.FolderEditField.Value=app.selpath;
            %
            newpath = uigetdir(app.defaultPath,'Folder to store Log files');
            if ischar(newpath) % if canceled, this is a number 0
                if isfolder(newpath)  % can only use this with character array
                    app.defaultPath=newpath;  % update the path if a new one given
                    app.FolderEditField.Value=app.defaultPath;
                    app.FolderButton.BackgroundColor=[0.96,0.96,0.96]; % from [1,.9,.6]
                end
            end
            figure(app.UIFigure); % Restore focus to this window
            app.FillFileNameFields();

        end

        % Button pushed function: RadioConfigButton
        function RadioConfigButtonPushed(app, event)
            radioconfig(app.nnp);
        end

        % Button pushed function: TestConnectionButton
        function TestConnectionButtonPushed(app, event)
            modePM=app.nnp.read(7,'2010',1, 'uint8'); % this should produce an array that I need to convert to string
            dmessage=sprintf('%d ',modePM);
           app.TopMessage(dmessage);% for now there is no way to go to bottom programatically, so don't append
        end

        % Button pushed function: WOROffButton
        function WOROffButtonPushed(app, event)
            if app.nnp.worOff
                dmessage='WOR Off';
            else
                dmessage='Error setting WOR Off';
            end
            app.TopMessage(dmessage);
        end

        % Button pushed function: WOROnButton
        function WOROnButtonPushed(app, event)
            if app.nnp.worOn(20)
                dmessage='WOR On';
            else
                dmessage='Error setting WOR On';
            end
            app.TopMessage(dmessage);
        end

        % Button pushed function: DisableScriptsButton
        function DisableScriptsButtonPushed(app, event)
            app.nnp.enterWaiting();
            %app.nnp.networkOff();
            app.nnp.nmt(7, '8F'); %disable scripts
            dmessage='Scripts Disabled';
            app.TopMessage(dmessage);
        end

        % Button pushed function: StartScriptsButton
        function StartScriptsButtonPushed(app, event)
            app.nnp.nmt(7, '8E'); %enable scripts
            app.nnp.nmt(7,'B3',7); % Run startup script
            dmessage='Scripts Enabled and Startup Script Run';
            app.TopMessage(dmessage);
        end

        % Button pushed function: RestoreScriptsButton
        function RestoreScriptsButtonPushed(app, event)
       
        [fn, pn]=uigetfile('*.mat','Scripts Backup File to Restore',app.defaultPath);
        if fn
            RestoreFileName=fullfile(pn, fn);
            d = load(RestoreFileName);
            try
                backupRestoreScripts(app.nnp, d.scriptData);
                 dmessage='Scripts are Restored';
                app.TopMessage(dmessage);
                app.nnp.setRadio('worInt', 20);
    
            catch
    %             disp('could not open restoreData from file')
                dmessage='could not open restoreData from file';
                app.TopMessage(dmessage);
            end
        end
        end

        % Button pushed function: BackupPMButton
        function BackupPMButtonPushed(app, event)
         %Backup PM to file
          restoreData = backupRestorePM(app.nnp);
          save(app.PMBackupName, 'restoreData');
          if size(restoreData,2)==401 % earlier than PM version 405
              % create a version that can be used with 405 and above
              % Start with PM399 restoreData which is 401 element cell array
              a=restoreData(1:157);
              b=restoreData(170:223);
              c=restoreData(242:370);
              c{29}=150;
              d=num2cell(zeros(1,50)); %makes array of zeros
              e=restoreData(371:401);
              % Concatenate them together to get the new restoreData
              restoreData405=[a,b,c,d,e];
              restoreData=restoreData405;
              [fp,fname,fext] = fileparts(app.PMBackupName); 
              PMBackupName405=fullfile(fp, strcat(fname,'405',fext));  %appended backup name 
              save(PMBackupName405, 'restoreData');% save as mat file
          end
         
         dmessage='Finished backing up PM';
        app.TopMessage(dmessage);
        end

        % Button pushed function: BackupRMButton
        function BackupRMButtonPushed(app, event)
          
          remotemodules=uint8(eval(app.RemoteModulesEditField.Value)); % saved as text in edit field
          restoreData = backupRestoreRM(app.nnp, remotemodules);
          save(app.RMBackupName, 'restoreData');
          dmessage='Finished backing up Remote Modules';
        app.TopMessage(dmessage);
        end

        % Button pushed function: RestorePMButton
        function RestorePMButtonPushed(app, event)
            [fn, pn]=uigetfile('*.mat','Power Module Backup File to Restore',app.defaultPath);
            if fn
                RestoreFileName=fullfile(pn, fn);
                d = load(RestoreFileName);
                try
                    backupRestorePM(app.nnp, d.restoreData);
                    dmessage='Power Module Memory Restored';
                    app.TopMessage(dmessage);
                catch
                    %             disp('could not open restoreData from file')
                    dmessage='could not open restoreData from file';
                    app.TopMessage(dmessage);
                end
            end
        end

        % Button pushed function: RestoreRMButton
        function RestoreRMButtonPushed(app, event)
            [fn, pn]=uigetfile('*.mat','Remote Module Backup File to Restore',app.defaultPath);
            if fn
                RestoreFileName=fullfile(pn, fn);
                d = load(RestoreFileName);
                try
                    remotemodules=uint8(eval(app.RemoteModulesEditField.Value)); % saved as text in edit field
                    backupRestoreRM(app.nnp, remotemodules, d.restoreData);
                     dmessage='Remote Modules Memory Restored';
                    app.TopMessage(dmessage);
                catch
                    dmessage='could not open restoreData from file';
                    app.TopMessage(dmessage);
                end
            end
        end

        % Button pushed function: BackupScriptsButton
        function BackupScriptsButtonPushed(app, event)
            scriptData = backupRestoreScripts(app.nnp);
            save(app.ScriptBackupName, 'scriptData');
          dmessage='Finished backing up Scripts';
        app.TopMessage(dmessage);
        end

        % Button pushed function: RemoteModuleCurrentConfigurationButton
        function RemoteModuleCurrentConfigurationButtonPushed(app, event)
            rmParametersStatus(app.nnp);
        end

        % Button pushed function: SetDateTimeButton
        function SetDateTimeButtonPushed(app, event)
        if pmDateTimeSet(app.nnp)
            dmessage='Error setting data and time';
        else
            dmessage='Date and Time updated in PM';
        end
        app.TopMessage(dmessage);
        end

        % Button pushed function: NetworkOnButton
        function NetworkOnButtonPushed(app, event)
            if app.nnp.enterWaiting()
                if app.nnp.networkOn()
                    dmessage='Network On';
                else
                    dmessage='Network On failed';
                end
            else
                dmessage='Waiting command failed';
            end
           app.TopMessage(dmessage);
        end

        % Button pushed function: NetworkOffButton
        function NetworkOffButtonPushed(app, event)
            if app.nnp.enterWaiting()
                if app.nnp.networkOff()
                    dmessage='Network Off';
                else
                    dmessage='Network Off failed';
                end
            else
                dmessage='Waiting command failed';
            end
           app.TopMessage(dmessage);
        end

        % Callback function
        function WOROnButton_2Pushed(app, event)
           if app.nnp.worOn(20)
            dmessage='WOR On';
           else 
               dmessage='Error setting WOR On';
           end
           app.TopMessage(dmessage);
        end

        % Value changed function: SelectBackupButton
        function SelectBackupButtonValueChanged(app, event)
            value = app.SelectBackupButton.Value;
            % Toggle row of buttons to enable
            if value % when true show backup panel
                app.RestoreMemoryPanel.Visible=false;
                app.BackupMemoryPanel.Visible=true;
                app.SelectRestoreButton.Value=false;
            else % when false high back and show restore
                app.RestoreMemoryPanel.Visible=true;
                app.BackupMemoryPanel.Visible=false;
                app.SelectRestoreButton.Value=true;              
            end

        end

        % Value changed function: SelectRestoreButton
        function SelectRestoreButtonValueChanged(app, event)
            value = app.SelectRestoreButton.Value;
             if value % when true show backup panel
                app.RestoreMemoryPanel.Visible=true;
                app.BackupMemoryPanel.Visible=false;
                app.SelectBackupButton.Value=false;
            else % when false high back and show restore
                app.RestoreMemoryPanel.Visible=false;
                app.BackupMemoryPanel.Visible=true;                
                app.SelectBackupButton.Value=true;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ishandle(app.callingFigure) % check that is is valid
                figure(app.callingFigure); % bring to front
            end
            
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'BackupRestoreMemory';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.RMBackupNameEditField = uieditfield(app.UIFigure, 'text');
            app.RMBackupNameEditField.Editable = 'off';
            app.PMBackupNameEditField = uieditfield(app.UIFigure, 'text');
            app.PMBackupNameEditField.Editable = 'off';
            app.BackupandRestoreMemoryLabel = uilabel(app.UIFigure);
            app.BackupandRestoreMemoryLabel.Text = 'Backup and Restore PM and RM Memory and Configurations ';
            app.FolderButton = uibutton(app.UIFigure, 'push');
            app.FolderButton.ButtonPushedFcn = createCallbackFcn(app, @FolderButtonPushed, true);
            app.FolderButton.Text = 'Folder';
            app.FolderEditField = uieditfield(app.UIFigure, 'text');
            app.FolderEditField.Editable = 'off';
            app.AssumethatyouhavealreadyrunnnpNNPAPIandpassednnptothisappLabel = uilabel(app.UIFigure);
            app.AssumethatyouhavealreadyrunnnpNNPAPIandpassednnptothisappLabel.Text = 'Assume that you have already run nnp=NNPAPI and passed nnp to this app';
            app.MessagesTextAreaLabel = uilabel(app.UIFigure);
            app.MessagesTextAreaLabel.Text = 'Messages';
            app.MessagesTextArea = uitextarea(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'need to add clear button and append messages onto the string each time a new message is'; 'added to the queue. when setting scroll location is available.'};
            app.PMBackupNameLabel = uilabel(app.UIFigure);
            app.PMBackupNameLabel.Text = 'PM Backup Name';
            app.RMBackupNameLabel = uilabel(app.UIFigure);
            app.RMBackupNameLabel.Text = 'RM Backup Name';
            app.PreparationPanel = uipanel(app.UIFigure);
            app.PreparationPanel.Title = 'Preparation';
            app.WOROffButton = uibutton(app.PreparationPanel, 'push');
            app.WOROffButton.ButtonPushedFcn = createCallbackFcn(app, @WOROffButtonPushed, true);
            app.WOROffButton.Text = {'WOR'; 'Off'};
            app.DisableScriptsButton = uibutton(app.PreparationPanel, 'push');
            app.DisableScriptsButton.ButtonPushedFcn = createCallbackFcn(app, @DisableScriptsButtonPushed, true);
            app.DisableScriptsButton.Text = {'Disable'; 'Scripts'};
            app.RadioConfigButton = uibutton(app.PreparationPanel, 'push');
            app.RadioConfigButton.ButtonPushedFcn = createCallbackFcn(app, @RadioConfigButtonPushed, true);
            app.RadioConfigButton.Text = {'Radio'; 'Config'};
            app.TestConnectionButton = uibutton(app.PreparationPanel, 'push');
            app.TestConnectionButton.ButtonPushedFcn = createCallbackFcn(app, @TestConnectionButtonPushed, true);
            app.TestConnectionButton.Text = {'Test'; 'Connection'};
            app.RemoteModulesLabel = uilabel(app.PreparationPanel);
            app.RemoteModulesLabel.Text = 'Remote Modules';
            app.RemoteModulesEditField = uieditfield(app.PreparationPanel, 'text');
            app.RemoteModulesEditField.Value = '[1:6,9]';
            app.NetworkOnButton = uibutton(app.PreparationPanel, 'push');
            app.NetworkOnButton.ButtonPushedFcn = createCallbackFcn(app, @NetworkOnButtonPushed, true);
            app.NetworkOnButton.Text = {'Network'; 'On'};
            app.BackupMemoryPanel = uipanel(app.UIFigure);
            app.BackupMemoryPanel.Title = 'Backup Memory';
            app.BackupPMButton = uibutton(app.BackupMemoryPanel, 'push');
            app.BackupPMButton.ButtonPushedFcn = createCallbackFcn(app, @BackupPMButtonPushed, true);
            app.BackupPMButton.Tooltip = {'Create a .mat file of restoreData.  If earlier than PM405'; ' a second copy is created for 405 and above'};
            app.BackupPMButton.Text = {'Backup'; 'PM'};
            app.BackupRMButton = uibutton(app.BackupMemoryPanel, 'push');
            app.BackupRMButton.ButtonPushedFcn = createCallbackFcn(app, @BackupRMButtonPushed, true);
            app.BackupRMButton.Tooltip = {''};
            app.BackupRMButton.Text = {'Backup'; 'RM'};
            app.BackupScriptsButton = uibutton(app.BackupMemoryPanel, 'push');
            app.BackupScriptsButton.ButtonPushedFcn = createCallbackFcn(app, @BackupScriptsButtonPushed, true);
            app.BackupScriptsButton.Text = {'Backup'; 'Scripts'};
            app.TurnonCoilfromCECTBEFOREbackupscriptsLabel = uilabel(app.BackupMemoryPanel);
            app.TurnonCoilfromCECTBEFOREbackupscriptsLabel.Text = {'Turn on Coil'; 'from CE/CT'; 'BEFORE '; 'backup scripts'};
            app.RestoreMemoryPanel = uipanel(app.UIFigure);
            app.RestoreMemoryPanel.Title = 'Restore Memory';
            app.RestoreMemoryPanel.Visible = 'off';
            app.RestorePMButton = uibutton(app.RestoreMemoryPanel, 'push');
            app.RestorePMButton.ButtonPushedFcn = createCallbackFcn(app, @RestorePMButtonPushed, true);
            app.RestorePMButton.Tooltip = {'GUI will open to select .mat file with restoreData'};
            app.RestorePMButton.Text = {'Restore'; 'PM'};
            app.RestoreRMButton = uibutton(app.RestoreMemoryPanel, 'push');
            app.RestoreRMButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreRMButtonPushed, true);
            app.RestoreRMButton.Text = {'Restore'; 'RM'};
            app.RestoreScriptsButton = uibutton(app.RestoreMemoryPanel, 'push');
            app.RestoreScriptsButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreScriptsButtonPushed, true);
            app.RestoreScriptsButton.Text = {'Restore'; 'Scripts'};
            app.SetDateTimeButton = uibutton(app.RestoreMemoryPanel, 'push');
            app.SetDateTimeButton.ButtonPushedFcn = createCallbackFcn(app, @SetDateTimeButtonPushed, true);
            app.SetDateTimeButton.Text = {'Set Date'; '& Time'};
            app.TurnonCoilfromCECTBEFORErestorescriptsLabel = uilabel(app.RestoreMemoryPanel);
            app.TurnonCoilfromCECTBEFORErestorescriptsLabel.Text = {'Turn on Coil'; 'from CE/CT'; 'BEFORE '; 'restore scripts'};
            app.RestartScriptsPanel = uipanel(app.UIFigure);
            app.RestartScriptsPanel.Title = 'Restart Scripts';
            app.StartScriptsButton = uibutton(app.RestartScriptsPanel, 'push');
            app.StartScriptsButton.ButtonPushedFcn = createCallbackFcn(app, @StartScriptsButtonPushed, true);
            app.StartScriptsButton.Text = {'Start'; 'Scripts'};
            app.WOROnButton = uibutton(app.RestartScriptsPanel, 'push');
            app.WOROnButton.ButtonPushedFcn = createCallbackFcn(app, @WOROnButtonPushed, true);
            app.WOROnButton.Text = {'WOR'; 'On'};
            app.NetworkOffButton = uibutton(app.RestartScriptsPanel, 'push');
            app.NetworkOffButton.ButtonPushedFcn = createCallbackFcn(app, @NetworkOffButtonPushed, true);
            app.NetworkOffButton.Text = {'Network'; 'Off'};
            app.OtherHelpfulDataPanel = uipanel(app.UIFigure);
            app.OtherHelpfulDataPanel.Title = 'Other Helpful Data';
            app.RemoteModuleCurrentConfigurationButton = uibutton(app.OtherHelpfulDataPanel, 'push');
            app.RemoteModuleCurrentConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @RemoteModuleCurrentConfigurationButtonPushed, true);
            app.RemoteModuleCurrentConfigurationButton.Text = {'Remote Module'; 'Current Configuration'};
            app.ScriptBackupNameEditField = uieditfield(app.UIFigure, 'text');
            app.ScriptBackupNameEditField.Editable = 'off';
            app.ScriptBackupNameLabel = uilabel(app.UIFigure);
            app.ScriptBackupNameLabel.Text = 'Script Backup Name';
            app.SelectBackupButton = uibutton(app.UIFigure, 'state');
            app.SelectBackupButton.ValueChangedFcn = createCallbackFcn(app, @SelectBackupButtonValueChanged, true);
            app.SelectBackupButton.Text = {'Select'; 'Backup'};
            app.SelectBackupButton.Value = true;
            app.SelectRestoreButton = uibutton(app.UIFigure, 'state');
            app.SelectRestoreButton.ValueChangedFcn = createCallbackFcn(app, @SelectRestoreButtonValueChanged, true);
            app.SelectRestoreButton.Text = {'Select'; 'Restore'};
            app.UserIDEditField = uieditfield(app.UIFigure, 'text');
            app.UserIDEditField.Editable = 'off';
            app.UserIDLabel = uilabel(app.UIFigure);
            app.UserIDLabel.Text = 'UserID';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = backupRestoreMemory(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end