classdef displayNNPSystemDiagram < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        DefaultMenu                 matlab.ui.container.Menu
        OpenMenu                    matlab.ui.container.Menu
        SaveMenu                    matlab.ui.container.Menu
        SaveAsMenu                  matlab.ui.container.Menu
        EditMenu                    matlab.ui.container.Menu
        TitlesMenu                  matlab.ui.container.Menu
        BodyMenu                    matlab.ui.container.Menu
        SubwayMenu                  matlab.ui.container.Menu
        AddNodeMenu                 matlab.ui.container.Menu
        InsertNodeMenu              matlab.ui.container.Menu
        DeleteNodeMenu              matlab.ui.container.Menu
        DeleteChannelMenu           matlab.ui.container.Menu
        ConfigMenu                  matlab.ui.container.Menu
        LabelsMenu                  matlab.ui.container.Menu
        HideMenu                    matlab.ui.container.Menu
        ColorMenu                   matlab.ui.container.Menu
        NoneMenu                    matlab.ui.container.Menu
        PNGMenu_2                   matlab.ui.container.Menu
        AsDisplayedMenu             matlab.ui.container.Menu
        FullViewMenu                matlab.ui.container.Menu
        ExportMenu                  matlab.ui.container.Menu
        ProfileMenu                 matlab.ui.container.Menu
        XMLMenu                     matlab.ui.container.Menu
        UserIDEditField             matlab.ui.control.EditField
        StartwiththeSubwayTabLabel  matlab.ui.control.Label
        FilenameEditField           matlab.ui.control.EditField
        FileNameLabel               matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        SubwayTab                   matlab.ui.container.Tab
        NOTELabel                   matlab.ui.control.Label
        NoteListthedistalnodesinnumerialLabel  matlab.ui.control.Label
        EditRowsPanel               matlab.ui.container.Panel
        RefreshDiagramsButton       matlab.ui.control.Button
        UndoButton                  matlab.ui.control.Button
        DeleteRowButton             matlab.ui.control.Button
        AddRowButton                matlab.ui.control.Button
        SubwayDiagramButton         matlab.ui.control.Button
        NetworkTable                matlab.ui.control.Table
        ArmTab                      matlab.ui.container.Tab
        Label                       matlab.ui.control.Label
        UIDiagramTable              matlab.ui.control.Table
        ShowLegendCheckBox          matlab.ui.control.CheckBox
        BlackLabelsButton           matlab.ui.control.StateButton
        EditLocationsButton         matlab.ui.control.StateButton
        ClearMessageButton          matlab.ui.control.Button
        Messages                    matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        diagramTable=table('Size',[4,7],'VariableTypes',{'double','string','logical','string','string','string','string'});
        subwayTable={};
        unDelSubwayTable={}; % before deleting a row, save a backup to allow undelete
        networkTable=table('Size',[0,6],'VariableTypes',{'double','double','double','string','double','logical'});
        networkRow=0; %currently selected row of network table
        sortedSubwayTable={};
        newSWTRow={}; % new row to add
        nodeTable=[]; % table of node locations, colors and label locations
        firstPMindex=14; % location of first PM node in nodeTable
        electrodeTable=[]; % table of electrode locations an label locations
        nodeImageFiles=[]; % cell array of image file names for nodes
        legendImageFiles=[]; % cell array of image files for legend or descriptions
        backgroundImageFiles=[]; % cell array of background image files (body only, body+nodes and electrode)

        hSysFig=[]; % Handle to figure        
        hSysAxes=[]; % Handle to axes for image       
        hLegend=[];% handle to legend image
        hBgIm=[]; % handle to background image        
        hNodeIm={}; % handles to node images - handles require cell array        
        hNodeLab={}; % handles to node labels      
        hElectrodeP={}; % handles to electrode plots -handles require cell array
        hElectrodeLab={}; % handles to electrode labels
        horA={'left','center','right'};
        diagramTitle={'P0 System Diagram'};
        hDiagramTitle=[]; % handle to diagram Title

        branchSequence=[]; %intermediate
        branchSequence1=[];
        branchSequence2=[];
        nextRows=[];
        nextBranches=[];
        swPMImageFiles=[]; % cell array of PM image file names for nodes in subway diagram
        swNodeImageFiles=[]; % cell array of image file names for nodes in subway diagram
        hSwFig=[]; % handle to subway figure
        hSwAxes=[]; % handle to subway axes
        hSwBgIm=[] % handle to subway background image
        
        hSwNodeIm={}; % handles to subway node images
        hSwNodeLab={}; % handles to subway node labels
        hSwLines={}; % handles to subway lines
        hSwDiagramTitle=[]; % handle to subway diagram title
        hSwElectrodeLab={}; % handles to subway node labels

        subwayTitle={'P0 Subway Diagram'};
        hSubwayTitle=[]; % handle to diagram Title
        rmAnchors=[]; % anchor points for remote moduels
        pmCorners=[];
        rmPoints=[];
        netPath=[];
        swLabelOffset=[-5,20;85,20;85,140;-5,140]; %relative to RM anchor points
        swLabelJust={'right','left','left','right'};
        saveFile=[];
        callingFigure=[];
        % Code for auto file open
         userFolders=struct;  % userfiles, code, user, config, data, log
         defaultSuffix='_SubwayDiagramData.mat';
         defaultSubSuffix='_SubwayDiagram.png';
         defaultSysSuffix='_SystemDiagram.png';
         defaultProfSuffix='_ProfileSubway.mat';
         defaultXMLSuffix='_SubwayDiagramData.xml';
         defaultName='P00_SubwayDiagramData.mat';
    end
    
    methods (Access = public)
        
        function loadDefaults(app)
            % load in all default tables and values from .mat file
            %  for default use this:
           ld=load('nnpDiagramReference.mat');
%             fields={'backgroundImageFiles','electrodeTable','nodeImageFiles','nodeTable'};
%             fields={'backgroundImageFiles','electrodeTable','legendImageFiles','netPath','nodeImageFiles',...
%                 'nodeTable','pmCorners','rmAnchors','rmPoints','swNodeImageFiles'};
            fields={'diaRef','swRef', 'imRef'};
            iMissing = find(~isfield(ld, fields));            
            str = [];
            for i=1:length(iMissing)
                str = [str ', ' fields{iMissing(i)}];
            end
            if ~isempty(str)
                warndlg(['Invalid Default Data File.  Does not contain variable(s): ' str '.'], 'Open File')
                return;
            end
%             app.saveFile = TempFile;
%             app.FilenameEditField.Value=app.saveFile;
%           Later I could leave these all in structure form, but already
%           had the variables defined by this point
            app.backgroundImageFiles=ld.imRef.backgroundImageFiles;
            app.legendImageFiles=ld.imRef.legendImageFiles;
            app.nodeImageFiles=ld.imRef.nodeImageFiles;
            app.swNodeImageFiles=ld.imRef.swNodeImageFiles;

            app.nodeTable=ld.diaRef.nodeTable;
            app.electrodeTable=ld.diaRef.electrodeTable;
            
            app.pmCorners=ld.swRef.pmCorners;
            app.rmAnchors=ld.swRef.rmAnchors;
            app.rmPoints=ld.swRef.rmPoints;
            app.netPath=ld.swRef.netPath;

            app.TopMessage('loaded default file successfully');
        end
        
        function loadDefaultSW(app)
           ld=load('subwayTableDefaults.mat');
            fields={'subwayTable','newSWTRow'};
            iMissing = find(~isfield(ld, fields));            
            str = [];
            for i=1:length(iMissing)
                str = [str ', ' fields{iMissing(i)}];
            end
            if ~isempty(str)
                warndlg(['Invalid Default Data File.  Does not contain variable(s): ' str '.'], 'Open File')
                return;
            end
           app.subwayTable=ld.subwayTable;    
           app.newSWTRow=ld.newSWTRow;
           app.diagramTable=app.subwayTable(:,[3,5,6,19:22]);
           app.networkTable=app.subwayTable(:,[1:3,5,4,7]);
           app.UIDiagramTable.Data=app.diagramTable;
           app.NetworkTable.Data=app.networkTable;
        end

        function TopMessage(app,message)
            app.Messages.Value=vertcat(message, string(app.Messages.Value)); % put new message on top
        end
        %***********************
        function displayAllNE(app)
            % display all the nodes and electrodes in subwayTable
            % update background image
            
            hold(app.hSysAxes,'off'); % clear data on next write
            app.hBgIm=imshow(app.backgroundImageFiles{1},'Parent',app.hSysAxes,'InitialMagnification',50,'Interpolation','bilinear'); % handle to background image
            
            % row by row of the display table
            rows=size(app.subwayTable,1); % number of rows to read
            % display node for first row
            for r=1:rows
                % add N>0 condition
                app.displayNode(r); %
                for ch=1:4
                    app.displayChannel(r,ch);
                end
            end
            
            app.displayTitle(); % display title         
            app.displayLegend(); % display legend
           
        end

        function displayChannel(app,row,chan)
            %channel locations are col 15-18
            % channel labels are col 19-22
            if chan<1
                return
            end
            r=row;
            nodeLoc=app.subwayTable.nLoc(r); %need this for color
            if nodeLoc==14||nodeLoc==15 % PM nodes
                return % skip the rest if PM
            end

            if nodeLoc==0
                return
            end
            
            electrodeLoc=app.subwayTable{r,chan+14}; 
            if electrodeLoc==0
                return
            end
            nColor=app.nodeTable{nodeLoc,3:5}; % color for this node
            x=app.electrodeTable.X(electrodeLoc);
            y=app.electrodeTable.Y(electrodeLoc);
            mfc=nColor;
            mec=[0,0,0]; % black edges
            lw=1;
            sz=10;

            if chan<3
               mType='o'; % circle              
            else
                mType='^'; % triangle 
            end
            if app.BlackLabelsButton.Value
                lColor=[0,0,0];
            else
                lColor=app.nodeTable{nodeLoc,9:11}; % separate color easier to read
            end

            if r<=size(app.hElectrodeP,1)&&chan<=size(app.hElectrodeP,2)
                delete(app.hElectrodeP{r,chan}); % first delete if not already deleted
                delete(app.hElectrodeLab{r,chan});
            end

            hold(app.hSysAxes,'on');
            app.hElectrodeP{r,chan}=plot(app.hSysAxes,x,y,mType,'MarkerFaceColor',mfc,'MarkerEdgeColor',mec,'MarkerSize',sz);
            app.hElectrodeP{r,chan}.LineWidth=lw;
            % add labels
            x=app.electrodeTable.XLab(electrodeLoc);
            y=app.electrodeTable.YLab(electrodeLoc);
            str=app.subwayTable{r,18+chan};
            hA=app.horA{app.electrodeTable.JLab(electrodeLoc)+1};
            app.hElectrodeLab{r,chan}=text(x,y,str);
            app.hElectrodeLab{r,chan}.Color=lColor;
            app.hElectrodeLab{r,chan}.FontSize=16;
            app.hElectrodeLab{r,chan}.VerticalAlignment='middle';
            app.hElectrodeLab{r,chan}.HorizontalAlignment=hA;
        end

        function displayNode(app,row) % new
            r=row;
            node=app.subwayTable.dNode(r); % node number
            nodeLoc=app.subwayTable.nLoc(r); %location position
            if nodeLoc<1 % not set yet
                app.TopMessage('node possition not set');
                return
            end
            nodeOri=app.subwayTable.dOri(r)+1; % to make 1 based index 
            nodeF=app.nodeImageFiles{nodeLoc,nodeOri};
            [Ni,~,Na]=imread(nodeF);
            hold(app.hSysAxes,'on');
            if node<=size(app.hNodeIm,2)
                delete(app.hNodeIm{node});
                delete(app.hNodeLab{node});
            end
            app.hNodeIm{node}=imshow(Ni,'Parent',app.hSysAxes,'InitialMagnification',50,'Interpolation','bilinear');
            app.hNodeIm{node}.AlphaData=Na;
            app.hNodeIm{node}.XData=app.nodeTable.X(nodeLoc);
            app.hNodeIm{node}.YData=app.nodeTable.Y(nodeLoc);
            % add labels
            x=app.nodeTable.XLab(nodeLoc);
            y=app.nodeTable.YLab(nodeLoc);
            str=num2str(node);
            
            app.hNodeLab{node}=text(x,y,str);
            app.hNodeLab{node}.Color=[0,0,0];
            app.hNodeLab{node}.FontSize=12;
            app.hNodeLab{node}.FontWeight='bold';
            app.hNodeLab{node}.VerticalAlignment='middle';
            app.hNodeLab{node}.HorizontalAlignment='center';

        end
        
        function changeNodeLocation(app,row) %new
            r=row;
            node=app.subwayTable.dNode(r); % go back to subway table
            
            %delete current node and label
            delete(app.hNodeIm{node}); % handles to node images - handles require cell array
            delete(app.hNodeLab{node}); % handles to node labels
            %get new location
            axes(app.hSysAxes); %make sure you are working with correct axes
            [x,y]=ginput(1);
            P=[app.nodeTable.XLab,app.nodeTable.YLab]; % use XLab intead of X because X is upper right corner of image
            [k,~]=dsearchn(P,[x,y]); %find nearest
            app.subwayTable.nLoc(r)=k;
            % The display table should not need to change
             app.displayNode(r);
             for i=1:4
                 app.displayChannel(r,i);
             end

        end
        
        function changeChanLocation(app,row,chan) %new
            % add parameter to set k=0, to not draw.
            r=row;           
            % delete current electrode and label
             if r<=size(app.hElectrodeP,1)&&chan<=size(app.hElectrodeP,2)
                delete(app.hElectrodeP{r,chan}); % handles to node images - handles require cell array
                delete(app.hElectrodeLab{r,chan}); % handles to node labels
             end
            %get new location
            axes(app.hSysAxes); %make sure you are working with correct axes
            [x,y]=ginput(1);
            P=[app.electrodeTable.X,app.electrodeTable.Y];
            [k,~]=dsearchn(P,[x,y]); %find nearest
            %update the subwayTable
            app.subwayTable{r,chan+14}=k;
            % the diagramTable and UIdiagramTable do not need to change          
            app.displayChannel(r,chan);

        end

        function deleteChannelLocation(app,row,chan)
         % add parameter to set k=0, to not draw.
            r=row;           
            % delete current electrode and label
             if r<=size(app.hElectrodeP,1)&&chan<=size(app.hElectrodeP,2)
                delete(app.hElectrodeP{r,chan}); % handles to node images - handles require cell array
                delete(app.hElectrodeLab{r,chan}); % handles to node labels
             end
            %get new location
            k=0;
            app.subwayTable{r,chan+14}=k;
            % the diagramTable and UIdiagramTable do not need to change          
            app.displayChannel(r,chan);    
        end

        %****************


        %*****************
        function displayTitle(app)
            % Add Title
            app.hDiagramTitle=title(app.hSysAxes,app.diagramTitle); % handle to diagram Title
            app.hDiagramTitle.FontSize=14;
            
        end

        function displaySubwayTitle(app)
            % Add subwayTitle
            app.hSubwayTitle=title(app.hSwAxes,app.subwayTitle); % handle to diagram Title
            app.hSubwayTitle.FontSize=14;
            
        end

        function displayLegend(app)
%             if app.hLegend~=[]  % going to clear it out either way
                delete(app.hLegend)
%             end
            if app.ShowLegendCheckBox.Value
                fnLegend=app.legendImageFiles{1}; % for now only have one
                [Li,~,La]=imread(fnLegend);
                hold(app.hSysAxes,'on');

                app.hLegend=imshow(Li,'Parent',app.hSysAxes,'InitialMagnification',50,'Interpolation','bilinear');
                app.hLegend.AlphaData=La;
                app.hLegend.XData=95; % hardwired for now - will need to make variable
                app.hLegend.YData=275;
            end
        end
        

        function CreateSortedSubwayTable(app)
        % fill in the virtual channels and rotations, then sort by virtual
        % channels to make plotting more efficient
        virtualMap=[3,4,1,2];
        pNodeRotations=zeros(10,1);
        tableRows=size(app.subwayTable,1);
        for i=1:tableRows
                if app.subwayTable.dChan(i)>2
                    app.subwayTable.dRotate(i)=1;
                    app.subwayTable.dVirtual(i)=virtualMap(app.subwayTable.dChan(i));
                else
                    app.subwayTable.dRotate(i)=0;
                    app.subwayTable.dVirtual(i)=app.subwayTable.dChan(i);
                end
                dNode=app.subwayTable.dNode(i);
                pNodeRotations(dNode)=app.subwayTable.dRotate(i); %setting up to set the pRotations
         end
         for i=1:tableRows
             pNode=app.subwayTable.pNode(i);
             % for PM row, pNode will be zero, so skip the virtual settings
             if pNode>0
                 app.subwayTable.pRotate(i)=pNodeRotations(pNode);
                 if app.subwayTable.pRotate(i)==1
                     app.subwayTable.pVirtual(i)=virtualMap(app.subwayTable.pChan(i));
                 else
                     app.subwayTable.pVirtual(i)=app.subwayTable.pChan(i);
                 end
             end
         end
         % At this point we can sort by pVirtual (descending) and they would all
            % line up correctly.
            app.sortedSubwayTable=sortrows(app.subwayTable,{'pVirtual'},{'descend'});

            for i=1:tableRows %(need to reset the rows and columns for the algorithm to work)
                app.sortedSubwayTable.colDiag(i)=0;
            end
        end


% *********************************
        function getBranchSequence(app, side)
            %  Find the sequence of rows from sortedSubwayTable that correspond to PM branch
            % branch is either 1 or 2 for the channel coming from power module
            % find the row corresponding to branch
            % Last thing to fix is I would like to shift proximal nodes over to line up
            % with first distal node, without interferin with other nodes.
            % The outer loop
            % columns are: {'rowCT','pNode','pChan','dNode','dChan','rowDiag','colDiag','proxRotate','distRotate','pVirtual','dVirtual'}
            % new: 
%             1 pNode
%             2 pChan
%             3	dNode
%             4	dChan
%             5	dType
%             6	dOri
%             7	pend
%             8	nLoc
%             9	rowDiag
%             10 colDiag
%             11 pRotate
%             12 dRotate
%             13 pVirtual
%             14 dVirtual
%             15 eLoc1
%             16 eLoc2
%             17 eLoc3
%             18 eLoc4
%             19 eLab1
%             20 eLab2
%             21 eLab3
%             22 eLab4

            % I have now included a PM row in which case pNode=0, dNode=7
            branchStart=0; 
            totalCables=size(app.sortedSubwayTable,1);
            for i=1:totalCables
                if app.sortedSubwayTable.pNode(i)==7
                    if app.sortedSubwayTable.pChan(i)==side
                        branchStart=i;
                    end
                end
            end
            if branchStart==0
                app.branchSequence=[];
                return;
            end
   % I need to go back an replace every "r" with rSq             
            r=1; % row of branchSequence
            rSST=branchStart; % row of sortedSubwayTable
            dr=1; % diagram row
%             branchSequence=zeros(r,11); % {'rowCT','pNode','pChan','dNode','dChan','rowDiag','colDiag','proxRotate','distRotate','pVirtual','dVirtual'}
            %This resets branchSequence to the starting row only
            app.branchSequence=app.sortedSubwayTable(rSST,:); % copy whole row, then edit as needed
            app.branchSequence.rowDiag(r)=dr;% diagram row
            app.branchSequence.colDiag(r)=1;% start in col 1
            app.branchSequence.pVirtual(r)=app.branchSequence.pChan(r);% start with pChan,dChan
            app.branchSequence.dVirtual(r)=app.branchSequence.dChan(r);% start with pChan,dChan

             % get branches for this dist node
            app.nextBranches=0;
            indices=[]; % as long as I have indices, I can get node and channel
            
            j=1;
            for i=1:size(app.sortedSubwayTable,1) % look through list
                if app.sortedSubwayTable.pNode(i)==app.branchSequence.dNode(r) % the next node in the chain
                    indices(j)=i;
                    j=j+1;
                    app.nextBranches=app.nextBranches+1; %number of branches from node
                end
            end
            app.nextRows=indices;


            if app.nextBranches>0 % if no branches then nextNode is terminal
                L1=0;%first level
                rList=app.nextRows;
                dr1=dr+1;
                for n1=rList
                    L1=L1+1;
                    r=r+1; % next row of sequence
            
%                     [branchSequence, nextRows,nextBranches] = fillSeqRow(app.sortedSubwayTable,branchSequence, n1, r, dr1,L1);
                    app.fillSequenceRow(n1, r, dr1);
                   
                    if app.nextBranches>0 % if no branches then nextNode is terminal
                        L2=0;
                        dr2=dr1+1;             
                        rList2=app.nextRows;
                        for n2=rList2
                            L2=L2+1;
                            r=r+1; % next row of sequence
                            
%                             [branchSequence, nextRows,nextBranches] = fillSeqRow(app.sortedSubwayTable,branchSequence, n2, r, dr2,L2);
                                app.fillSequenceRow(n2, r, dr2);
            
                            if app.nextBranches>0 % if no branches then nextNode is terminal
                                L3=0;
                                %                     dr=dr+1;
                                dr3=dr2+1;
                                rList3=app.nextRows;
                                for n3=rList3
                                    L3=L3+1;% L is >1 for multiple branches
                                    r=r+1; % next row of sequence
                                   app.fillSequenceRow(n3, r, dr3);
            
                                    if app.nextBranches>0 % if no branches then nextNode is terminal - fourth level
                                        L4=0;
                                        %                     dr=dr+1;
                                        dr4=dr3+1;
                                        rList4=app.nextRows;
                                        for n4=rList4 % fourth loop
                                            L4=L4+1;% L is >1 for multiple branches
                                            r=r+1; % next row of sequence
                                            app.fillSequenceRow(n4, r, dr4);
            
                                            if app.nextBranches>0 % if no branches then nextNode is terminal - fifth level
                                            L5=0;
                                            %                     dr=dr+1;
                                            dr5=dr4+1;
                                            rList5=app.nextRows;
                                            for n5=rList5 % fifth loop
                                                L5=L5+1;% L is >1 for multiple branches
                                                r=r+1; % next row of sequence
                                                app.fillSequenceRow(n5, r, dr5);
                
            
                                                    if app.nextBranches>0 % if no branches then nextNode is terminal - sixth level
                                                    L6=0;
                                                    %                     dr=dr+1;
                                                    dr6=dr5+1;
                                                    rList6=app.nextRows;
                                                    for n6=rList6 % sixth loop
                                                        L6=L6+1;% L is >1 for multiple branches
                                                        r=r+1; % next row of sequence                                            
                                                        app.fillSequenceRow(n6, r, dr6);
                        
                                                     end % sixth loop
                                                    end % sixth level
                                             end % fifth loop
                                            end % fifth level
                                        end % fourth loop
                                    end % fourth level
                                end % third loop
                            end % third level
                        end % second loop
                    end % second level        
                end % first loop
            end % first level
            % Add rotation of proximal node
            temp=app.branchSequence.pNode(:); % col 2 is proximal node
            maxPNode=max(temp,[],'all'); % get highest number
            temp=app.branchSequence.dNode(:); % col 4 is distal node
            maxDNode=max(temp,[],'all');
            maxNodes=max(maxDNode,maxPNode);
            pNodeRotations=zeros(maxNodes,1); % start with all zeros, pNode=2

            for r=1:size(app.branchSequence,1)
                dNode=app.branchSequence.dNode(r);
                if app.branchSequence.dRotate(r)==1 %distRotate=9 use distal here because that determines which rotate
                    pNodeRotations(dNode)=1; %keeping track of which have been rotated
                end
            end
            virtualMap=[3,4,1,2];

            for r=1:size(app.branchSequence,1)
                pNode=app.branchSequence.pNode(r);
                app.branchSequence.pRotate(r)=pNodeRotations(pNode);%.pRotate=8
                % Add virtual channel numbers
                if app.branchSequence.pRotate(r)==1
                    app.branchSequence.pVirtual(r)=virtualMap(app.branchSequence.pChan(r));%pVirtual=10,pChan=3
                end
                if app.branchSequence.dRotate(r)==1 %distRotate=9
                    app.branchSequence.dVirtual(r)=virtualMap(app.branchSequence.dChan(r)); %pVirtual=11, dChan=5
                end
            end
        end

        function fillSequenceRow(app, rSST, rSq, rD)
            %rSST=row in sortedSubwayTable
            %rD= row in subway diagram
            %rS= row in sequence
            % if the branchSequence is <rSq, then need to concatinate new
            % row onto the table
            if size(app.branchSequence,1)<rSq
                app.branchSequence=[app.branchSequence;app.sortedSubwayTable(rSST,:)];
            else
                app.branchSequence{rSq,:}=app.sortedSubwayTable{rSST,:}; % copy whole row, then edit as needed
            end
            app.branchSequence.rowDiag(rSq)=rD;% diagram row
            app.branchSequence.pVirtual(rSq)=app.branchSequence.pChan(rSq);% start with pChan,dChan
            app.branchSequence.dVirtual(rSq)=app.branchSequence.dChan(rSq);% start with pChan,dChan
%           app.nextNode=app.branchSequence.dNode(rSq); % could use directly but separated out to make easier to read


            % need to look at columns of other rows first
            % Use this to align columns correctly:
            sameRowFlags=app.branchSequence.rowDiag(:)==rD; % look for any other nodes in same diagram row
            % Next row may be is necessary to align the succeeding columns as well
            
            if ~isempty(sameRowFlags) && any(sameRowFlags)
                sameRowCol=app.branchSequence.colDiag(sameRowFlags); % columns currently in use for this row
                maxCurrentRow=max(sameRowCol,[],'all'); % ,maximum column in current row
            else
                maxCurrentRow=0;
            end
            % Need to compare to column of proximal node.
            pNode=app.branchSequence.pNode(rSq);
            dNodes=app.branchSequence.dNode(:); % all current dNodes in sequence
            nRows=find(dNodes==pNode);
            pCol=app.branchSequence.colDiag(nRows(1)); %current column before adjusting
            newCol=max([maxCurrentRow+1,pCol],[],'all');
            app.branchSequence.colDiag(rSq)=newCol; % column

            if app.branchSequence.dChan(rSq)>2
                app.branchSequence.dRotate(rSq)=1; % rotate distal node  (will use to fill in prox rotations
            end

            % get branches for this dist node
            app.nextBranches=0;
            indices=[]; % as long as I have indices, I can get node and channel

            j=1;
            for i=1:size(app.sortedSubwayTable,1) % look through list
                if app.sortedSubwayTable.pNode(i)==app.branchSequence.dNode(rSq) % the next node in the chain
                    indices(j)=i;
                    j=j+1;
                    app.nextBranches=app.nextBranches+1; %number of branches from node
                end
            end
            app.nextRows=indices;
        end

        function plotSubwayDiagram(app,BS)
            nLines=size(BS,1);
            % generate table of node locations
            nodeLocations=zeros(10,2); % will never need all 10 but easier this way
            nodeType=zeros(10,1); % use for node image
            %1=PM, 2=Stim, 3=BP
            for i=1:size(app.subwayTable,1)  %get types from display table
                n=app.subwayTable.dNode(i);
                nt=app.subwayTable.dType(i);
                switch nt
                    case 'PM'
                        nodeType(n)=1;
                    case 'Stim'
                        nodeType(n)=2;
                    case 'BP'
                        nodeType(n)=3;
                    otherwise
                        nodeType(n)=2; % if something else typed in.  Will add checks later
                end
            end

            for i=1:nLines % separate look to get node locations first
                imRow=i;
                pNode=BS.pNode(imRow);
                dNode=BS.dNode(imRow);
                dRow=BS.rowDiag(imRow);
                dCol=BS.colDiag(imRow);
                dRot=BS.dRotate(imRow); %need rotion for labels later
                nodeLocations(dNode,1)=dRow;
                nodeLocations(dNode,2)=dCol;
                nodeLocations(dNode,3)=dRot;

                % what if I already plotted PM from prior branch sequence?
                if pNode==7 % power module is an extra plot IN ADDITION to the other dNode plots
                    nX=280;
                    nY=40;
                    imNode=app.swNodeImageFiles{1,1};%'SWPM.png'  1=PM, 2=Stim, 3=BP, Col 2=Rev
                    [Ni,~,Na]=imread(imNode); % read in the node data and alpha
                    hold(app.hSwAxes,"on");
                    imPM=imshow(Ni,'Parent',app.hSwAxes,'InitialMagnification',100,'Interpolation','bilinear');
                    imPM.AlphaData=Na;   % set alpha to the roated alpha
                    imPM.XData=nX;
                    imPM.YData=nY;
                end
                % plot node
                imRot=BS.dRotate(imRow);

                % saving the locations as rows and col rather than pixels
                % because I need to use to get column offsets between p and d
                %****dRow and dCol refer to the dNode, not pNode

                nX=app.rmAnchors(dRow,dCol,1);
                nY=app.rmAnchors(dRow,dCol,2);
                % add condition here for STim vs BP
%                 if nodeType{dRow}
                if imRot==1
                    imNode=app.swNodeImageFiles{nodeType(dNode),2};%  1=PM, 2=Stim, 3=BP, Col 2=Rev
                else
                    imNode=app.swNodeImageFiles{nodeType(dNode),1};%  1=PM, 2=Stim, 3=BP, Col 2=Rev
                end

                [Ni,~,Na]=imread(imNode); % read in the node data and alpha
                hold(app.hSwAxes,"on");

                app.hSwNodeIm{i}=imshow(Ni,'Parent',app.hSwAxes,'InitialMagnification',100,'Interpolation','bilinear');
                app.hSwNodeIm{i}.AlphaData=Na;   % set alpha to the roated alpha
                app.hSwNodeIm{i}.XData=nX;
                app.hSwNodeIm{i}.YData=nY;
            end
            for i=1:nLines
                % plot line
                % pRow,pCol = starting point locations for prox node where 1,1 is table
                % pX, pY = starting point offset pixels for prox node where 1,1 is table
                % if pNode==7, need set of lines to define PM locations and starting
                % lines.  For now use pmX=280, pmY=40
                % pmline1=(280,60), (140,60), (140,360)
                % pmline2=(280,60), (180,60), (180,360)
                % pathVector=netPath{proxV,distV,column}
                imRow=i;
                pNode=BS.pNode(imRow);
                dNode=BS.dNode(imRow);
                pVirtual=BS.pVirtual(imRow);
                dVirtual=BS.dVirtual(imRow);
                dRow=BS.rowDiag(imRow);
                dCol=BS.colDiag(imRow);
                pend=BS.pend(imRow);
                if pNode==7 % PM module
                    % here I need to add a check for pChan, 
                    % because pChan =2 would be to the right
                    % xline=[360, 140+offset, 140+offset] or
                    % xline=[360, 180+offset, 180+offset]
                    % where offset is (dCol-1)*320
                    yline=[60,60,360];
                    pChan=BS.pChan(imRow);
                    switch pChan
                        case 1
                            xbeg=280;
                        case 2
                            xbeg=360;
                    end                                    
                    if dVirtual==1
                        xend=140+(dCol-1)*320;
                        
                    else
                        xend=180+(dCol-1)*320;
                    end
                    xline=[xbeg,xend,xend];
                else
                    pRow=nodeLocations(pNode,1);
                    pCol=nodeLocations(pNode,2);
                    indexCol=dCol-pCol+1; % where to get pathway
                    NP=app.netPath{pVirtual,dVirtual,indexCol}; % gives me the point to point
                    netPathLine=app.rmPoints{NP,[1:2]}; % 2D array of x and Y in columns
                    xline=app.rmAnchors(pRow,pCol,1)+netPathLine(:,1)';  % x vector
                    yline=app.rmAnchors(pRow,pCol,2)+netPathLine(:,2)';  % y vector
                end
                % now plot the line
                if pend
                    app.hSwLines{i}=plot(app.hSwAxes,xline,yline,'b--','LineWidth',2);
                else
                app.hSwLines{i}=plot(app.hSwAxes,xline,yline,'b','LineWidth',4);
                end

                % Add condition for removed or absent lines (dotted or
                % other color).
            end  
    % Create channel label array from node table
        channelLabelMatrix=cell(9,4); %9x4 cell array
        for i=1:size(app.subwayTable,1)
            if app.subwayTable.dNode(i)~=7
              r=app.subwayTable.dNode(i);
              for c=1:4
              channelLabelMatrix{r,c}=app.subwayTable{i,18+c}; %labels are col 19:21
              end
            end
        end    
    % plot node labels
        virtualMap=[3,4,1,2];
%         m=1; % may want to figure better number for labels later.  Could make label handles 2D
        for i=1:9
       % PM not included in nodelocation array because not part of 5x5 RM array
            if i==7
                pmLabelX=app.pmCorners{1,1}+40;
                pmLabelY=app.pmCorners{1,2}+80;
                pmLabelStr='PM';
                app.hSwNodeLab{i}=text(app.hSwAxes,pmLabelX,pmLabelY,pmLabelStr);
                app.hSwNodeLab{i}.FontSize=14;
                app.hSwNodeLab{i}.FontWeight='bold';
                app.hSwNodeLab{i}.VerticalAlignment='middle';
                app.hSwNodeLab{i}.HorizontalAlignment='center';
            else
                 if nodeLocations(i,1)~=0
                    r=nodeLocations(i,1);
                    c=nodeLocations(i,2);
                    x=app.rmAnchors(r,c,1);
                    y=app.rmAnchors(r,c,2);
                    xn=x+40;
                    yn=y+80;
                    nLabelStr=num2str(i);
                    app.hSwNodeLab{i}=text(app.hSwAxes,xn,yn,nLabelStr);
                    app.hSwNodeLab{i}.FontSize=18;
                    app.hSwNodeLab{i}.FontWeight='bold';
                    app.hSwNodeLab{i}.VerticalAlignment='middle';
                    app.hSwNodeLab{i}.HorizontalAlignment='center';
                    % plot channel labels
                    % already have x and y for nodes, need relative
                    % locations for labels
                    %         swLabelOffset=[-5,20;85,20;85,140;-5,140]; %relative to RM anchor points
                    %         swLabelJust={'right','left','left','right'};
                    % need to use virtual channel, not actual channel based
                    % on rotation
                    for lab=1:4                    
                        lNode=i;
                        lStr=channelLabelMatrix(lNode,lab); %unroated value
                        if ~isempty(lStr)
                        lRot=nodeLocations(lNode,3);
                        if lRot==1 % rotated node
                            lChan=virtualMap(lab);
                        else
                            lChan=lab;                        
                        end
                        %plot label
                        app.hSwElectrodeLab{i,lab}=text(app.hSwAxes,x+app.swLabelOffset(lChan,1),y+app.swLabelOffset(lChan,2),lStr);
                        app.hSwElectrodeLab{i,lab}.HorizontalAlignment=app.swLabelJust{lChan}; % use virtual channel
                         app.hSwElectrodeLab{i,lab}.VerticalAlignment='middle';
                         app.hSwElectrodeLab{i,lab}.FontSize=10;
                        end
                    end
                end
            end
        end
        app.displaySubwayTitle();
    end
% **********************


        function createSubwayDiagram(app)
            app.CreateSortedSubwayTable();% create sortedSubwayTable,sorted by inverse of pVirtual
%             return; % temporary so I can test one piece at a time
            app.getBranchSequence(1);% branchSequence for PM1

            % subway background display
            hold(app.hSwAxes,'off'); % clear data on next write
            app.hSwBgIm=imshow(app.backgroundImageFiles{3},'Parent',app.hSwAxes,'InitialMagnification',50,'Interpolation','bilinear'); % handle to background image
            if ~isempty(app.branchSequence)
                app.branchSequence1=app.branchSequence;
                plot1=true;
            %get max offset column
            maxCol=max(app.branchSequence1.colDiag(:),[],'all');
            app.plotSubwayDiagram(app.branchSequence1);
            else
                maxCol=1;
                plot1=false;
            end
            
            app.getBranchSequence(2);% branchSequence for PM2
            if ~isempty(app.branchSequence)
                app.branchSequence2=app.branchSequence;
                shiftCol=app.branchSequence2.colDiag(:)+maxCol; % shift the whole tree over
                app.branchSequence2.colDiag=shiftCol;
                plot2=true;
                app.plotSubwayDiagram(app.branchSequence2);
            else
                plot2=false;
            end
            app.displaySubwayTitle();
            app.updateSWTableRowCol(); % update row,col in subwayTable
            % What to do if discontinuities are present (like missing cable)    
            % if pend is true, then plot dotted line instead of solid ADD   
        end
        
        function refreshDiagrams(app)
            %This whole group should probably be repeated each time a row
            %is added or deleted - so could create a function from this.  I
            %plan to edit rows from the subway diagram, so maybe it should
            %end up there

            app.diagramTable=app.subwayTable(:,[3,5,6,19:22]);
            app.UIDiagramTable.Data=app.diagramTable;
            app.networkTable=app.subwayTable(:,[1:3,5,4,7]);
            app.NetworkTable.Data=app.networkTable;
            % unfortunately, this requires some shuffling of figures to
            % make sure the diagrams get plotted correctly
            %
            figure(app.hSysFig);
            app.TabGroup.SelectedTab=app.ArmTab;
            pause(1);
            app.displayAllNE();
            %
            figure(app.hSwFig);
            app.TabGroup.SelectedTab=app.SubwayTab;
            pause(1);
            app.createSubwayDiagram(); % may not want this to always run because you need to set up table first
            pause(1);
            %
%             app.TabGroup.SelectedTab=app.ArmTab;
%             figure(app.hSysFig);
            figure(app.UIFigure); %return focus after nnp opened if needed
        end
        
        function updateSWTableRowCol(app)
            % update the subway diagram rows and columns based on
            % branchsequences.  Want it included for saving swtable
            % after getBranchSequence
            % updated afterward because the algorithm starts with all the
            % row and col values at 0
            swRows=zeros(9,9); % mapping of rows in subwayTable
            tableRows=size(app.subwayTable,1);
            for i=1:tableRows
                if app.subwayTable.pNode(i)>0 && app.subwayTable.dNode(i) >0
                    swRows(app.subwayTable.pNode(i),app.subwayTable.dNode(i))=i;
                end
            end

            % after getBranchSequence
            branchRows=size(app.branchSequence1,1);
            if branchRows>0
                for i=1:branchRows
                    stIndex=swRows(app.branchSequence1.pNode(i),app.branchSequence1.dNode(i)); % get ST index
                    if stIndex>0 %want to exclude these
                        app.subwayTable.rowDiag(stIndex)=app.branchSequence1.rowDiag(i);
                        app.subwayTable.colDiag(stIndex)=app.branchSequence1.colDiag(i);
                    else
                        % give a warning?
                    end
                end
            end
            branchRows=size(app.branchSequence2,1);
            if branchRows>0
                for i=1:branchRows
                    stIndex=swRows(app.branchSequence2.pNode(i),app.branchSequence2.dNode(i)); % get ST index
                    if stIndex>0 %want to exclude these
                        app.subwayTable.rowDiag(stIndex)=app.branchSequence2.rowDiag(i);
                        app.subwayTable.colDiag(stIndex)=app.branchSequence2.colDiag(i);
                    else
                        % give a warning?
                    end
                end
            end
        end
        

    end

    methods (Access = private)
         
        function UI2SubwayUpdate(app,row,col,NewData)
            % change the subwayTable from the UI display table
            % mapping for columns = [3,5,6,19:22]
            colmap=[3,5,6,19:22];
            swCol=colmap(col);
            app.subwayTable{row,swCol}=NewData;
        end
        
        function OpenFileByName(app,filename,filepath)
            if filename
                ld = load(fullfile(filepath, filename));
                TempFile = fullfile(filepath, filename);  % save filename as temporary until the file is checked
            else
                return;
            end
            fields = {'subwayTable','subwayTitle','diagramTitle'};
            iMissing = find(~isfield(ld, fields));

            str = [];
            for i=1:length(iMissing)
                str = [str ', ' fields{iMissing(i)}];
            end
            if ~isempty(str)
                warndlg(['Invalid subway Parameter File.  Does not contain variable(s): ' str '.'], 'Open File')
                return;
            end
            % switch to the diagram figure
            figure(app.hSysFig);
            % clear the current image before proceeding
            app.EditLocationsButton.Value=false; % stop editing when opening file
            [bgi,~,~]=imread(app.backgroundImageFiles{2}); % read in the background image
            app.hBgIm.CData=bgi; % update image data

            % If the file is invalid, we don't want to update anything, including the file name
            app.saveFile = TempFile;
            app.FilenameEditField.Value=app.saveFile;
            app.subwayTable=ld.subwayTable;
            if ~iscell(ld.subwayTitle)
                app.subwayTitle={ld.subwayTitle}; %from prior matlab versions
            else
                app.subwayTitle=ld.subwayTitle;
            end

            if ~iscell(ld.diagramTitle)
                app.diagramTitle={ld.diagramTitle}; %from prior matlab versions
            else
                app.diagramTitle=ld.diagramTitle;
            end
            %This whole group should probably be repeated each time a row
            %is added or deleted - so could create a function from this.  I
            %plan to edit rows from the subway diagram, so maybe it should
            %end up there
            
            app.diagramTable=app.subwayTable(:,[3,5,6,19:22]);
            app.networkTable=app.subwayTable(:,[1:3,5,4,7]);
            app.UIDiagramTable.Data=app.diagramTable;
            app.NetworkTable.Data=app.networkTable;
            % unfortunately, this requires some shuffling of figures to
            % make sure the diagrams get plotted correctly
            figure(app.hSysFig);
            app.TabGroup.SelectedTab=app.ArmTab;
            pause(1);
            app.displayAllNE();
            figure(app.hSwFig);
            app.TabGroup.SelectedTab=app.SubwayTab;
            pause(1);
            app.createSubwayDiagram(); % may not want this to always run because you need to set up table first
            pause(1);
            app.TabGroup.SelectedTab=app.ArmTab;
            figure(app.hSysFig);
            figure(app.UIFigure); %return focus after nnp opened if needed
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
           app.loadDefaults(); 
           app.loadDefaultSW();
           % show first background image
%            app.hSysFig=figure('Position',[651,73,786,900]); % create new figure.
           app.hSysFig=figure('Position',[604,93,786,900]); % create new figure.
           app.hSysAxes=axes(app.hSysFig,'Position',[0.0100    0.0100    0.9800    0.9500]); % Handle to axes for image
           app.hBgIm=imshow(app.backgroundImageFiles{1},'Parent',app.hSysAxes,'InitialMagnification',50,'Interpolation','bilinear'); % handle to background image
           % show subway subway background
           app.hSwFig=figure('Position',[604,93,786,900]); % create new figure.  Overlay on purpose
           app.hSwAxes=axes(app.hSwFig,'Position',[0.0100    0.0100    0.9800    0.9500]); % Handle to axes for image
           app.hSwBgIm=imshow(app.backgroundImageFiles{3},'Parent',app.hSwAxes,'InitialMagnification',50,'Interpolation','bilinear'); % handle to background image
            % display table
           
           figure(app.hSysFig);
           app.displayAllNE();
           if nargin>1
                app.callingFigure=varargin{1};
            end
            if nargin>2  % third is userFolders
                app.userFolders=varargin{2};  % userfiles, code, user, config, data, log
                app.UserIDEditField.Value=app.userFolders.code;
                % next need to load the profile
            end
            if isfield(app.userFolders,'config')&& ~isempty(app.userFolders.config)
                filename=[app.userFolders.code,app.defaultSuffix];
                app.defaultName=filename; % this is reduntant, could combine above
                filepath=app.userFolders.config;
                fullName=fullfile(filepath,filename);
                if exist(fullName,'file')==2  % make sure file exists before trying to open
                    app.OpenFileByName(filename,filepath);
                end
            end
        end

        % Button pushed function: ClearMessageButton
        function ClearMessageButtonPushed(app, event)
            app.Messages.Value='';
        end

        % Value changed function: EditLocationsButton
        function EditLocationsButtonValueChanged(app, event)
            value = app.EditLocationsButton.Value;
            % when true, want to allow user to set the location of
            % whichever row is selected
            if value
                % disable all table editing
                % display selection background
                [bgi,~,~]=imread(app.backgroundImageFiles{2}); % read in the background image
                app.hBgIm.CData=bgi; % update image data
            else
                % endable table editing
                % display selection background
                [bgi,~,~]=imread(app.backgroundImageFiles{1}); % read in the background image
                app.hBgIm.CData=bgi; % update image data
            end
        end

        % Value changed function: BlackLabelsButton
        function BlackLabelsButtonValueChanged(app, event)
            value = app.BlackLabelsButton.Value;
            app.displayAllNE();
        end

        % Menu selected function: DefaultMenu
        function DefaultMenuSelected(app, event)
            app.loadDefaults();
            app.EditLocationsButton.Value=false;
            app.displayAllNE();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
           if ishandle(app.hSysFig)
               delete(app.hSysFig);
           end
           if ishandle(app.hSwFig)
               delete(app.hSwFig);
           end
           if ishandle(app.callingFigure) % check that is is valid (has not been closed
                figure(app.callingFigure); % bring to front
           end
            delete(app)
            
        end

        % Menu selected function: FullViewMenu
        function FullViewMenuSelected(app, event)
            %% save high resolution
            % get file name
            switch app.TabGroup.SelectedTab
                case app.ArmTab
%                     defname='NNP System Diagram for P.png';
                    defName=fullfile(app.userFolders.config,[app.userFolders.code,app.defaultSysSuffix]);
                    pngFig=app.hSysAxes;
                    msg= 'Save NNP Body Diagram As...';
                case app.SubwayTab
%                     defname='NNP Subway Diagram for P.png';
                     defName=fullfile(app.userFolders.config,[app.userFolders.code,app.defaultSubSuffix]);
                    pngFig=app.hSwAxes;
                    msg= 'Save NNP Subway Diagram As...';
            end
            [filename, filepath] = uiputfile('.png', msg,defName);
            if filename
                fn=fullfile(filepath,filename);
                exportgraphics(pngFig,fn,'Resolution',200);
            end
        end

        % Value changed function: ShowLegendCheckBox
        function ShowLegendCheckBoxValueChanged(app, event)
            value = app.ShowLegendCheckBox.Value;
            app.displayLegend();
            % hide or show legend
        end

        % Button pushed function: SubwayDiagramButton
        function SubwayDiagramButtonPushed(app, event)
        % calculate and display subway diagram based on subwayTable
        % subwayTable will get updated when changes made to app.NetworkTable
        app.createSubwayDiagram();

        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            switch selectedTab
                case app.ArmTab
                    figure(app.hSysFig);
                case app.SubwayTab
                    figure(app.hSwFig);
            end
        end

        % Callback function: UIDiagramTable
        function UIDiagramTableSelectionChanged(app, event)
            selection = app.UIDiagramTable.Selection;
            if app.EditLocationsButton.Value
                % get location and change the currently selected electrode
                row=selection(1);
                if selection(2)==1 % node
                     app.TopMessage('node');
                     app.changeNodeLocation(row);
                else
                    if selection(2)>3  %chan
                        chan=selection(2)-3;
                         app.TopMessage('chan');
                         app.changeChanLocation(row,chan);
                    end
                end
                app.UIDiagramTable.Selection=[]; % clear selection in case you want to change same one again
                % or node with new value
            else

            end
            % need option here for show and orientation
            % these could be true whether editLocation is true or not
            % 
        end

        % Cell edit callback: UIDiagramTable
        function UIDiagramTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            col = event.Indices(2);
            row = event.Indices(1);          
%           oldData = event.PreviousData;
            % update subwayTable
            app.UI2SubwayUpdate(row,col,newData);
            switch col
                case 3 % node orientation
                    newstr=sprintf('r %d c %d new %d',row,col,newData);
                    app.TopMessage(newstr);
                    % check for node number
                    app.displayNode(row);
                    % update the table variable
                    % redraw the node
                otherwise

            end
        end

        % Cell edit callback: NetworkTable
        function NetworkTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            % add mapping between UINetworkTable and subwayTable
            net2SwCol=[1,2,3,5,4,7]; % columns in displayed network table
            swCol=net2SwCol(indices(2));
            app.subwayTable{indices(1),swCol}=newData;
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
            % save the individual user data
            % get a file name and save the data to that file
            % include a default file name.  How about rmParameters.mat
            defName=fullfile(app.userFolders.config,app.defaultName);
            [filename, pathname ] = uiputfile('*.mat', 'Save Body and Subway Diagram Data Variables',defName);
            if filename == 0
                figure(app.UIFigure); %return focus after nnp opened if needed
                return
            else
                app.saveFile = fullfile(pathname, filename);
                SaveMenuSelected(app, event);
                app.FilenameEditField.Value=app.saveFile;
            end
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            if isempty(app.saveFile)
                SaveAsMenuSelected(app, event);
            else
                subwayTable=app.subwayTable;  % save as local variable to save
                subwayTitle=app.subwayTitle;
                diagramTitle=app.diagramTitle;
                % I could save the figures as well - add later
                save(app.saveFile,'subwayTable','subwayTitle','diagramTitle'); 
                app.TopMessage('Subway Data saved to file');
            end
            figure(app.UIFigure); %return focus after nnp opened if needed
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            % load the subway data from a file
             if isfield(app.userFolders,'config')&& ~isempty(app.userFolders.config)
                    fname=[app.userFolders.code,app.defaultSuffix];
                    defName=fullfile(app.userFolders.config,fname); % name with path                  
            else
                defName=app.defaultName; % name without path
            end
            [filename, filepath] = uigetfile('.mat', 'Select Node and Electrode Placement File',defName); % subway Table file
            if filename
                app.OpenFileByName(filename,filepath);
            end
             %{  
            if filename
                ld = load(fullfile(filepath, filename));
                TempFile = fullfile(filepath, filename);  % save filename as temporary until the file is checked
            else
                return;
            end
            fields = {'subwayTable','subwayTitle','diagramTitle'};
            iMissing = find(~isfield(ld, fields));

            str = [];
            for i=1:length(iMissing)
                str = [str ', ' fields{iMissing(i)}];
            end
            if ~isempty(str)
                warndlg(['Invalid subway Parameter File.  Does not contain variable(s): ' str '.'], 'Open File')
                return;
            end
            % switch to the diagram figure
            figure(app.hSysFig);
            % clear the current image before proceeding
            app.EditLocationsButton.Value=false; % stop editing when opening file
            [bgi,~,~]=imread(app.backgroundImageFiles{2}); % read in the background image
            app.hBgIm.CData=bgi; % update image data

            % If the file is invalid, we don't want to update anything, including the file name
            app.saveFile = TempFile;
            app.FilenameEditField.Value=app.saveFile;
            app.subwayTable=ld.subwayTable;
            if ~iscell(ld.subwayTitle)
                app.subwayTitle={ld.subwayTitle}; %from prior matlab versions
            else
                app.subwayTitle=ld.subwayTitle;
            end

            if ~iscell(ld.diagramTitle)
                app.diagramTitle={ld.diagramTitle}; %from prior matlab versions
            else
                app.diagramTitle=ld.diagramTitle;
            end
            %This whole group should probably be repeated each time a row
            %is added or deleted - so could create a function from this.  I
            %plan to edit rows from the subway diagram, so maybe it should
            %end up there
            
            app.diagramTable=app.subwayTable(:,[3,5,6,19:22]);
            app.networkTable=app.subwayTable(:,[1:3,5,4,7]);
            app.UIDiagramTable.Data=app.diagramTable;
            app.NetworkTable.Data=app.networkTable;
            % unfortunately, this requires some shuffling of figures to
            % make sure the diagrams get plotted correctly
            figure(app.hSysFig);
            app.TabGroup.SelectedTab=app.ArmTab;
            pause(1);
            app.displayAllNE();
            figure(app.hSwFig);
            app.TabGroup.SelectedTab=app.SubwayTab;
            pause(1);
            app.createSubwayDiagram(); % may not want this to always run because you need to set up table first
            pause(1);
            app.TabGroup.SelectedTab=app.ArmTab;
            figure(app.hSysFig);
            figure(app.UIFigure); %return focus after nnp opened if needed
             %}
        end

        % Menu selected function: BodyMenu
        function BodyMenuSelected(app, event)
            prompt = {'Enter New Title for Body Diagram'};
            dlgtitle = 'Edit Title';
            dims = [1 35];
            definput = app.diagramTitle;
            app.diagramTitle = inputdlg(prompt,dlgtitle,dims,definput);
            % in order to update the diagram title, need the diagram
            % showing - which also means changing the tabs as well
            app.TabGroup.SelectedTab=app.ArmTab;
            figure(app.hSysFig);
            app.hDiagramTitle.String=app.diagramTitle;
        end

        % Menu selected function: SubwayMenu
        function SubwayMenuSelected(app, event)
            prompt = {'Enter New Title for Body Diagram'};
            dlgtitle = 'Edit Title';
            dims = [1 35];
            definput = app.subwayTitle;
            app.subwayTitle = inputdlg(prompt,dlgtitle,dims,definput);
            % showing - which also means changing the tabs as well
            app.TabGroup.SelectedTab=app.SubwayTab;
            figure(app.hSwFig);
            app.hSubwayTitle.String=app.subwayTitle;
        end

        % Button pushed function: AddRowButton
        function AddRowButtonPushed(app, event)
            % copy subwayTable to undelete
            app.unDelSubwayTable=app.subwayTable;
            % add row to end
            app.subwayTable=[app.subwayTable;app.newSWTRow];
            %update the network table and allow user to edit values before
            %updating diagrams
            app.diagramTable=app.subwayTable(:,[3,5,6,19:22]);
            app.UIDiagramTable.Data=app.diagramTable;
            app.networkTable=app.subwayTable(:,[1:3,5,4,7]);
            app.NetworkTable.Data=app.networkTable;
            % update other tables
            % update diagrams
        end

        % Button pushed function: DeleteRowButton
        function DeleteRowButtonPushed(app, event)
            % highlight row to delete
            row=app.networkRow;
            % ask for confirmation to delete that row
            % if yes, then
            if row>0
                msgfig=uifigure;
                msg=sprintf('Delete row %d from table?',row);
                title='Confirm Row Delete';
                selection=uiconfirm(msgfig,msg,title,'Options',{'Yes','Cancel'});
                delete(msgfig); %doesn't delete automatically in appdesigner
                switch selection
                    case 'Yes'
                        app.unDelSubwayTable=app.subwayTable;  % copy subwayTable to undelete
                        app.subwayTable(row,:)=[]; % clear that row
                        app.refreshDiagrams();
                end
                % update other tables
                % update diagrams
            else
                %message to select a row
            end
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            % ask for confirmation to revert to prior table
            % copy unDelSubwayTable to subwayTable
             app.subwayTable=app.unDelSubwayTable;
             app.refreshDiagrams();
            % update other tables
            % update diagrams
        end

        % Button pushed function: RefreshDiagramsButton
        function RefreshDiagramsButtonPushed(app, event)
            app.refreshDiagrams();
        end

        % Cell selection callback: NetworkTable
        function NetworkTableCellSelection(app, event)
            indices = event.Indices;
            app.networkRow=indices(1,1);
        end

        % Menu selected function: XMLMenu
        function XMLMenuSelected(app, event)
             % save the individual user data as XML file
            % get a file name and save the data to that file
            % include a default file name.  
            % start with the current filename, with xml extension for
            % default
            %{
            [~,a,~]=fileparts(app.saveFile);
            if isempty(a)
                defaultName='PxSubwayDiagramData.xml';
            else
                defaultName = [a,'.xml'];
            end
            %}
            defName=fullfile(app.userFolders.config,[app.userFolders.code,app.defaultXMLSuffix]);
            [filename, pathname ] = uiputfile('*.xml', 'Save Body and Subway Diagram Data Table as XML',defName);
            if filename == 0
                figure(app.UIFigure); %return focus after nnp opened if needed
                return
            else
                xmlSaveFile = fullfile(pathname, filename); % do not save this file name
                subwayTable=app.subwayTable;  % save as local variable to save
                writetable(subwayTable,xmlSaveFile,'FileType','xml','TableNodeName','subwayTable');
                app.TopMessage('Subway Data saved to XML file');
            end
            figure(app.UIFigure); %return focus after nnp opened if needed   
        end

        % Menu selected function: ProfileMenu
        function ProfileMenuSelected(app, event)
             % Import data for the profile from the subway diagram file to use as starting point for profile.
             % load the subway data from a file
%             [filename, filepath] = uigetfile('.mat', 'Select Node and Electrode Placement File'); % subway Table file
%             if filename
%                 ld = load([filepath filename]);
%                 TempFile = [filepath filename];  % save filename as temporary until the file is checked
%             else
%                 return;
%             end
%             fields = {'subwayTable','subwayTitle','diagramTitle'};
%             iMissing = find(~isfield(ld, fields));
% 
%             str = [];
%             for i=1:length(iMissing)
%                 str = [str ', ' fields{iMissing(i)}];
%             end
%             if ~isempty(str)
%                 warndlg(['Invalid subway Parameter File.  Does not contain variable(s): ' str '.'], 'Open File')
%                 return;
%             end
%             subwayTable=ld.subwayTable;

            Istim=strcmp(app.subwayTable{:,'dType'},"Stim");
            stimTable=app.subwayTable(Istim,[3,19:22]); % I only need node and labels
            stimTable=sortrows(stimTable,1); %sort by node
            nodeNum=size(stimTable,1);
            nodeChanRows=nodeNum*4;
            nodeChanArray=cell(nodeChanRows,3);

            for i=1:nodeNum
                for j=1:4
                    row=(i-1)*4+j;
                    nodeChanArray{row,1}=stimTable{i,1};%node
                    nodeChanArray{row,2}=j;%ch
                    nodeChanArray{row,3}=stimTable{i,j+1}; %label
                end
            end
            newData=cell(nodeChanRows,10);
            %colNum=struct('LastChecked', 1, 'Node', 2, 'Ch', 3, 'Muscle', 4, 'Amp', 5, 'ThPW', 6, 'MaxPW', 7, 'Type', 8, 'Flag', 9, 'Notes', 10);
            newData(:,[2,3,4])=nodeChanArray(:,[1,2,3]);

            % add default data
            defaultRow={200,0,0,'OTH',false,''};
            for i=1:nodeChanRows
                newData(i,[5:10])=defaultRow(1:6);
            end
            defName=fullfile(app.userFolders.config,[app.userFolders.code,app.defaultProfSuffix]);
           % defname='P0Profile.mat';
            [filename, filepath] = uiputfile('.mat','Save Profiler User Data',defName);
            if filename
                app.saveFile=fullfile(filepath, filename);
                data=newData; 
                save(fullfile(filepath, filename), 'data');
            end
            figure(app.UIFigure);
        end

        % Menu selected function: DeleteChannelMenu
        function DeleteChannelMenuSelected(app, event)
            %set the channel location to 0
            % basic equation is:
            % app.subwayTable{r,chan+14}=k; where k=0
            % need to first get row, r and chan
            % determine if the selected cell is a channel cell 
            % delete the contents of the cell
            % edit the cell location
            % update the diagram.
            selection = app.UIDiagramTable.Selection;
            % only allow delete while edit locationn is false
            if ~app.EditLocationsButton.Value && ~isempty(selection)
                % get location and change the currently selected electrode
                row=selection(1);
                if selection(2)>3  %chan
                    chan=selection(2)-3;                                      
                    app.deleteChannelLocation(row,chan);
                    % delete contents of cell
                    app.UIDiagramTable.Data{selection(1),selection(2)}="";
                    ev.Indices=selection;
                    ev.NewData="";
                    app.UIDiagramTableCellEdit(ev);
                   
                    app.TopMessage('chan deleted');  
                end

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.DefaultMenu = uimenu(app.FileMenu);
            app.DefaultMenu.MenuSelectedFcn = createCallbackFcn(app, @DefaultMenuSelected, true);
            app.DefaultMenu.Text = 'Default';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Text = 'SaveAs';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.TitlesMenu = uimenu(app.EditMenu);
            app.TitlesMenu.Text = 'Titles';
            app.BodyMenu = uimenu(app.TitlesMenu);
            app.BodyMenu.MenuSelectedFcn = createCallbackFcn(app, @BodyMenuSelected, true);
            app.BodyMenu.Text = 'Body';
            app.SubwayMenu = uimenu(app.TitlesMenu);
            app.SubwayMenu.MenuSelectedFcn = createCallbackFcn(app, @SubwayMenuSelected, true);
            app.SubwayMenu.Text = 'Subway ';
            app.AddNodeMenu = uimenu(app.EditMenu);
            app.AddNodeMenu.Enable = 'off';
            app.AddNodeMenu.Text = 'Add Node';
            app.InsertNodeMenu = uimenu(app.EditMenu);
            app.InsertNodeMenu.Enable = 'off';
            app.InsertNodeMenu.Text = 'Insert Node';
            app.DeleteNodeMenu = uimenu(app.EditMenu);
            app.DeleteNodeMenu.Enable = 'off';
            app.DeleteNodeMenu.Text = 'Delete Node';
            app.DeleteChannelMenu = uimenu(app.EditMenu);
            app.DeleteChannelMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteChannelMenuSelected, true);
            app.DeleteChannelMenu.Text = 'Delete Channel';
            app.ConfigMenu = uimenu(app.UIFigure);
            app.ConfigMenu.Text = 'Config';
            app.LabelsMenu = uimenu(app.ConfigMenu);
            app.LabelsMenu.Text = 'Labels';
            app.HideMenu = uimenu(app.LabelsMenu);
            app.HideMenu.Text = 'Hide';
            app.ColorMenu = uimenu(app.LabelsMenu);
            app.ColorMenu.Text = 'Color';
            app.NoneMenu = uimenu(app.LabelsMenu);
            app.NoneMenu.Text = 'None';
            app.PNGMenu_2 = uimenu(app.UIFigure);
            app.PNGMenu_2.Text = 'PNG';
            app.AsDisplayedMenu = uimenu(app.PNGMenu_2);
            app.AsDisplayedMenu.Visible = 'off';
            app.AsDisplayedMenu.Enable = 'off';
            app.AsDisplayedMenu.Text = 'As Displayed';
            app.FullViewMenu = uimenu(app.PNGMenu_2);
            app.FullViewMenu.MenuSelectedFcn = createCallbackFcn(app, @FullViewMenuSelected, true);
            app.FullViewMenu.Text = 'Full View';
            app.ExportMenu = uimenu(app.UIFigure);
            app.ExportMenu.Text = 'Export';
            app.ProfileMenu = uimenu(app.ExportMenu);
            app.ProfileMenu.MenuSelectedFcn = createCallbackFcn(app, @ProfileMenuSelected, true);
            app.ProfileMenu.Text = 'Profile';
            app.XMLMenu = uimenu(app.ExportMenu);
            app.XMLMenu.MenuSelectedFcn = createCallbackFcn(app, @XMLMenuSelected, true);
            app.XMLMenu.Text = 'XML';
            app.Messages = uitextarea(app.UIFigure);
            app.ClearMessageButton = uibutton(app.UIFigure, 'push');
            app.ClearMessageButton.ButtonPushedFcn = createCallbackFcn(app, @ClearMessageButtonPushed, true);
            app.ClearMessageButton.Text = 'clear';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.SubwayTab = uitab(app.TabGroup);
            app.SubwayTab.Title = 'Subway';
            app.NetworkTable = uitable(app.SubwayTab);
            app.NetworkTable.ColumnName = {'pNode'; 'pChan'; 'dNode'; 'dType'; 'dChan'; 'Pending'};
            app.NetworkTable.RowName = {};
            app.NetworkTable.ColumnEditable = true;
            app.NetworkTable.CellEditCallback = createCallbackFcn(app, @NetworkTableCellEdit, true);
            app.NetworkTable.CellSelectionCallback = createCallbackFcn(app, @NetworkTableCellSelection, true);
            app.SubwayDiagramButton = uibutton(app.SubwayTab, 'push');
            app.SubwayDiagramButton.ButtonPushedFcn = createCallbackFcn(app, @SubwayDiagramButtonPushed, true);
            app.SubwayDiagramButton.Text = 'Subway';
            app.EditRowsPanel = uipanel(app.SubwayTab);
            app.EditRowsPanel.Tooltip = {'May want to shift these to the menus'};
            app.EditRowsPanel.Title = 'Edit Rows';
            app.AddRowButton = uibutton(app.EditRowsPanel, 'push');
            app.AddRowButton.ButtonPushedFcn = createCallbackFcn(app, @AddRowButtonPushed, true);
            app.AddRowButton.Text = {'Add'; 'Row'};
            app.DeleteRowButton = uibutton(app.EditRowsPanel, 'push');
            app.DeleteRowButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteRowButtonPushed, true);
            app.DeleteRowButton.Text = {'Delete'; 'Row'};
            app.UndoButton = uibutton(app.EditRowsPanel, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Text = 'Undo';
            app.RefreshDiagramsButton = uibutton(app.EditRowsPanel, 'push');
            app.RefreshDiagramsButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshDiagramsButtonPushed, true);
            app.RefreshDiagramsButton.Text = {'Refresh'; 'Diagrams'};
            app.NoteListthedistalnodesinnumerialLabel = uilabel(app.SubwayTab);
            app.NoteListthedistalnodesinnumerialLabel.Tooltip = {'It has to do with how the data is stored in the table if you start with the default'};
            app.NoteListthedistalnodesinnumerialLabel.Text = {'List the distal stim nodes, '; 'dNode) in numerial order'; ' for now (a bug to '; 'be sorted out).'};
            app.NOTELabel = uilabel(app.SubwayTab);
            app.NOTELabel.Text = 'NOTE';
            app.ArmTab = uitab(app.TabGroup);
            app.ArmTab.Title = 'Arm';
            app.EditLocationsButton = uibutton(app.ArmTab, 'state');
            app.EditLocationsButton.ValueChangedFcn = createCallbackFcn(app, @EditLocationsButtonValueChanged, true);
            app.EditLocationsButton.Text = 'Edit Locations';
            app.BlackLabelsButton = uibutton(app.ArmTab, 'state');
            app.BlackLabelsButton.ValueChangedFcn = createCallbackFcn(app, @BlackLabelsButtonValueChanged, true);
            app.BlackLabelsButton.Text = 'Black Labels';
            app.ShowLegendCheckBox = uicheckbox(app.ArmTab);
            app.ShowLegendCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowLegendCheckBoxValueChanged, true);
            app.ShowLegendCheckBox.Text = 'Show Legend';
            app.ShowLegendCheckBox.Value = true;
            app.UIDiagramTable = uitable(app.ArmTab);
            app.UIDiagramTable.ColumnName = {'dNode'; 'dType'; 'Ori'; 'eLab1'; 'eLab2'; 'eLab3'; 'eLab4'};
            app.UIDiagramTable.RowName = {};
            app.UIDiagramTable.ColumnEditable = true;
            app.UIDiagramTable.CellEditCallback = createCallbackFcn(app, @UIDiagramTableCellEdit, true);
            app.UIDiagramTable.SelectionChangedFcn = createCallbackFcn(app, @UIDiagramTableSelectionChanged, true);
            app.UIDiagramTable.Multiselect = 'off';
            app.Label = uilabel(app.ArmTab);
            app.Label.Text = 'Fill in channel labels starting at channel 1 (black dot on node) and proceed clockwise';
            app.FileNameLabel = uilabel(app.UIFigure);
            app.FileNameLabel.Text = 'FileName';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.StartwiththeSubwayTabLabel = uilabel(app.UIFigure);
            app.StartwiththeSubwayTabLabel.Text = {'Start with the Subway Tab'; 'Fill in the node and network connections'; 'Proceed to the Arm Tab '; 'Fill in the electrode names'};
            app.UserIDEditField = uieditfield(app.UIFigure, 'text');
            app.UserIDEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = displayNNPSystemDiagram(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end