classdef emgDisplayRawCue < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlotEMGFigure            matlab.ui.Figure
        ProcessPanel             matlab.ui.container.Panel
        UnblankEditField         matlab.ui.control.NumericEditField
        UnblankmsLabel           matlab.ui.control.Label
        SyncEditField            matlab.ui.control.NumericEditField
        SyncEditFieldLabel       matlab.ui.control.Label
        ProcessButton            matlab.ui.control.Button
        WFLGainEditField         matlab.ui.control.NumericEditField
        WFLGainEditFieldLabel    matlab.ui.control.Label
        ScaleButton              matlab.ui.control.Button
        CommandGainEditField     matlab.ui.control.NumericEditField
        CmdGainLabel             matlab.ui.control.Label
        CommandOffsetEditField   matlab.ui.control.NumericEditField
        CmdOffsetLabel           matlab.ui.control.Label
        ControlTabGroup          matlab.ui.container.TabGroup
        ControlPropTab           matlab.ui.container.Tab
        MaybeaddASFoptionsLabel  matlab.ui.control.Label
        ControlLogicalTab        matlab.ui.container.Tab
        D5ThreshSlider           matlab.ui.control.Slider
        D5ThreshSliderLabel      matlab.ui.control.Label
        HLtimeout                matlab.ui.control.NumericEditField
        HLTimeoutLabel           matlab.ui.control.Label
        D5Threshold              matlab.ui.control.NumericEditField
        D5ThreshLabel            matlab.ui.control.Label
        Tab                      matlab.ui.container.Tab
        GraphTabGroup            matlab.ui.container.TabGroup
        PropTab                  matlab.ui.container.Tab
        AutoASFButton            matlab.ui.control.Button
        ASFAxes                  matlab.ui.control.UIAxes
        LogicalTab               matlab.ui.container.Tab
        AutoTLButton             matlab.ui.control.Button
        AutoHLButton             matlab.ui.control.Button
        AutoD5Button             matlab.ui.control.Button
        TwitchLengthAxes         matlab.ui.control.UIAxes
        HighLowAxes              matlab.ui.control.UIAxes
        D5Axes                   matlab.ui.control.UIAxes
        Panel                    matlab.ui.container.Panel
        AutoWFL                  matlab.ui.control.Button
        OffsetEditField          matlab.ui.control.NumericEditField
        OffsetmsLabel            matlab.ui.control.Label
        SkipNaNSwitch            matlab.ui.control.Switch
        SkipNaNSwitchLabel       matlab.ui.control.Label
        GetFileButton            matlab.ui.control.Button
        ReplotButton             matlab.ui.control.Button
        ProcessedPanel           matlab.ui.container.Panel
        AutoCommandButton        matlab.ui.control.Button
        AutoWFLButton            matlab.ui.control.Button
        ScaledAxes               matlab.ui.control.UIAxes
        WFLAxes                  matlab.ui.control.UIAxes
        RawPanel                 matlab.ui.container.Panel
        LinktoRawXButton         matlab.ui.control.Button
        AutoRawButton            matlab.ui.control.Button
        RawAxes                  matlab.ui.control.UIAxes
        SampleRegionsAxes        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        LFileID=[]; % Description
        LogFileName=[];
        rawtype='single' % Description
        contractAll=[];
        relaxAll=[];
        WFLraw=[];
    end
    
    properties (Access = private)
        datapointsraw=0; % data points read in from raw file
        datapointsprocessed=0; %processed data points
        cuepointsprocessed=[];
        wflgain=10; % waveformlength gain
        rawdata=[]; % raw data at 1ms sampling rate
        cuedata=[];
        Xraw=[]; % Time scale for raw data
        Xprocessed=[]; % time scale for processed data
        % needed these xdata so that I have time scale for each plot to be
        % able to syncronize them with the link button
        XrawProcRatio=[]; % ratio of raw vs processed x scale, based on sync period
        WFL=[]; % waveform length calculations
        Command=[]; % Command scaled
        Scaled=[]; % WFL scaled
        CommandRS5=[]; % 5 point running sum of command
        CommandD5=[]; % 5 point difference
        CommandASF=[]; % Command processed with Adaptive stepsize filter
        High=[]; % CommandD5 crossing high threshold
        LowCross=[]; % Command crossing low threshold
        Quiet=[]; % input below quiet threshold - need to work on this part
        HLCount=[]; % Time between high and low count
        HLTwitch=[]; % HL Count when within allowed upper and lower limits
        Twitch=[]; % Twitch detected
        ASFpar=[1,2,3,5,8,13,18,25,33,38,33,25,18,13,8,5,3,2,1,1]; % adaptive stepsize filter parameters
        tabsg=[]; % graph tab group handles
        tabsc=[]; % control tab group handles
        LogFolder=[]; % Description
    end
    
    methods (Access = private)
        
        function PlotRawData(app)
            rawdata2d=fread(app.LFileID,[2,inf],app.rawtype); % two rows of data.  First is raw, second is the cue.  1=relax, 2=contract
            if ~isempty(rawdata2d)&&size(rawdata2d,2)>0
                app.rawdata=rawdata2d(1,:)'; % may need to transpose this
                app.cuedata=rawdata2d(2,:)'; % may need to transpose this
            else
                return; % do not try to plot
            end

            % check flag for ignoring NaN data
            % strip off NaN data
            if app.SkipNaNSwitch.Value>0
                app.rawdata=app.rawdata(isfinite(app.rawdata)); % remove NaN values
            end
            app.datapointsraw=size(app.rawdata,1);
            
            plot(app.RawAxes,app.rawdata);
            app.RawAxes.XLim=[0 app.datapointsraw];
            app.RawAxes.YLim=[0 255];
            %next I want to plot the sample regions.
            %             samplepoints=app.rawdata*0;
            %             j=1;
            %             for i=1:app.datapointsraw
            %
            %                 if j<app.UnblankEditField.Value
            %                     samplepoints(i)=1;
            %                 end
            %                 j=j+1;
            %                 if j>app.SyncEditField.Value
            %                     j=1;
            %                 end
            %             end
            plot(app.SampleRegionsAxes,app.cuedata);
            app.SampleRegionsAxes.XLim=[0 app.datapointsraw];
            app.SampleRegionsAxes.YLim=[0 2.1];
        end
        
        function CalcWFL(app)
            % calculate length of WFL data
            width=app.UnblankEditField.Value;
            sync=app.SyncEditField.Value;
            gain=app.WFLGainEditField.Value;
            points=floor((app.datapointsraw-1)/sync); %subtract 1 because of deltas
            app.datapointsprocessed=points; %for reference
            app.WFL=zeros(points,1); % preassign array
            % get rid of zero values because they throw off the calculations
            
            % calculate the absolute values of the differences
            % How do I factor in the offset when using stimulation and
            % artifact?
            for i=1:points
                a=((i-1)*sync)+1; %+app.OffsetEditField.Value; %start with Offset value
                b=a+width;
                delta=app.rawdata(a:b+1); %need the extra point for differences
                for k=1:width+1
                    if delta(k)==0
                        delta(k)=127; %remove zero data points
                    end
                end
                for k=1:width
                    delta(k)=abs(delta(k+1)-delta(k)); %absolute value of differences
                end
                app.WFL(i)=(sum(delta(1:width))/width)*gain;
            end
            % process the data
        end
        
        function WFLg=CalcWFLg(app,gain)
            % calculate length of WFL data
            width=app.UnblankEditField.Value;
            sync=app.SyncEditField.Value;
            %             gain=app.WFLGainEditField.Value;
            points=floor((app.datapointsraw-1)/sync); %subtract 1 because of deltas
            app.datapointsprocessed=points; %for reference
%             app.WFL=zeros(points,1); % preassign array            
             WFLg=zeros(points,1); % preassign array
            % get rid of zero values because they throw off the calculations
            
            % calculate the absolute values of the differences
            % How do I factor in the offset when using stimulation and
            % artifact?
            for i=1:points
                a=((i-1)*sync)+1; %+app.OffsetEditField.Value; %start with Offset value
                b=a+width;
                delta=app.rawdata(a:b+1); %need the extra point for differences
                for k=1:width+1
                    if delta(k)==0
                        delta(k)=127; %remove zero data points
                    end
                end
                for k=1:width
                    delta(k)=abs(delta(k+1)-delta(k)); %absolute value of differences
                end
%               app.WFL(i)=(sum(delta(1:width))/width)*gain;
                WFLg(i)=(sum(delta(1:width))/width)*gain;
            end
            % process the data
        end
        
        function CalcScaled(app)
            % The WFL will be clipped to 0-255, so need to do that before
            % calculating the gain and offset
            clipped=app.WFL;
            for i=1:app.datapointsprocessed
                if clipped(i)>255
                    clipped(i)=255;
                end
            end
            app.Scaled=(clipped-app.CommandOffsetEditField.Value)*app.CommandGainEditField.Value;
        end
        
        function CalcD5(app)
            app.CommandRS5=app.Command*0; %start with a copy of Command
            % since the running average can only be the past calculate
            % based on last 5
            for i=6:app.datapointsprocessed
                app.CommandRS5(i)=sum(app.Scaled(i-5:i));
                app.CommandD5(i)=app.CommandRS5(i)-app.CommandRS5(i-5);
            end
        end
        
        function ASFout = CalcASF(app,Scaled)
            dir=0;
            %             newdir=0;
            %             mag=0;
            ASFindex=1; % start with first index
            maxind=length(app.ASFpar);
            %             maxstep=app.ASFpar(ASFindex);
            Scaled=min(max(Scaled,0),255); % clip input to range of 0-255
            ASFout=Scaled; % Start by making an array that looks just like command
            for i=2:length(Scaled)
                delta=Scaled(i)-ASFout(i-1);
                newdir=sign(delta);
                mag=abs(delta);
                
                % Get the index to calculate the next step
                if newdir==0 %reset index
                    ASFindex=1;
                elseif newdir==dir %same direction
                    ASFindex=ASFindex+1;
                    if ASFindex>maxind
                        ASFindex=maxind;
                    end
                else
                    ASFindex=1; %change of direction
                end
                dir=newdir;
                % calculate the next step
                step=min(mag,app.ASFpar(ASFindex))*dir;
                ASFout(i)=min(max((ASFout(i-1)+step),0),255); %clip the output as I go along
            end
        end
        
        
        function CalcHighLow(app)
            % this function sets the flag for each time the D5 signal crosses above the high threshold,
            % after having previously either crossed the low threshold or having timed out from the previous crossing
            
            c=app.CommandD5; %starting array
            hth=app.D5Threshold.Value;  %high threshold
            
            lockout=5; % wait at least 5 samples before looking for h again - make parameter
            lockcount=lockout;
            lth=hth*-1;  % low threshold is opposite of high threshold
            h=c*0;  % create array of zeros for h, the high level
            %         l=h;
            hc=0;  % high counter, start at 0
            hlcount=h;  % high-low count, initialize with zeros
            hltwitch=h;  % twitch, initialize with zeros
            %         high=0;
            hcmax=app.HLtimeout.Value;  % if high count exceeds max, then reset counting
            for i=2:length(c)   % start with second point in arrays
                if h(i-1)==1 % prior high true so now looking for low  - first point will be false
                    hc=hc+1; %increment counter if looking for low
                    if hc> hcmax % timeout period
                        hc=0;h(i)=0;
                        hlcount(i)=0;
                    elseif c(i)<=lth %below low threshold
                        hlcount(i)=hc; % get hl count
                        hc=0; % reset hlcounter
                        hltwitch(i)=1; % note twitch
                        h(i)=0;
                        lockcount=lockout; %lock out the high search for 5 samples
                    else
                        h(i)=1; %since timeout has not ended, h continues to be 1
                    end
                else %looking for high
                    if lockcount>0
                        lockcount=lockcount-1;
                        % others should be zeros so leave them alone for now
                        
                    else
                        if c(i)>=hth
                            h(i)=1;
                            % others should be zeros so leave them alone for now
                        end
                    end
                end
            end
            app.High=h;
            app.HLCount=hlcount;
            app.HLTwitch=hltwitch;
            %         app.LowCross
            % app.Quiet %will add this later
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin<2
            LogFile=[];
            fn='none';
            else
                LogFile=varargin{1};
            end
            if isempty(LogFile) || ~exist(LogFile)==2
                app.LogFolder=[pwd '\'];
                [fn, pn]=uigetfile('*.*','Choose raw EMG file',app.LogFolder);
                if ischar(pn)
                    app.LogFolder=pn;
                end                
                app.LogFileName=fullfile(pn, fn);
            else
                app.LogFileName=LogFile;
                fn=LogFile;
            end
            app.LFileID=fopen(app.LogFileName);
            app.PlotEMGFigure.Position=[100,50,1000,1000]; %start shifted down so I can scale to fit and still be able to resize and scroll
            app.PlotRawData;
            figure(app.PlotEMGFigure); % Restore focus to this window
            app.datapointsprocessed=floor((app.datapointsraw-1)/app.SyncEditField.Value); %subtract 1 because of deltas
            app.XrawProcRatio=app.datapointsraw/app.datapointsprocessed;% ratio of raw to processed data points
            app.tabsg=app.GraphTabGroup.Children; % array of handles to tabs so I can find which is selected
            app.tabsc=app.ControlTabGroup.Children;% array of handles to tabs so I can find which is selected
            app.RawAxes.Title.Interpreter='none';
            app.RawAxes.Title.String=sprintf('Raw %s',fn);
        end

        % Button pushed function: ReplotButton
        function ReplotButtonPushed(app, event)
            frewind(app.LFileID);
            app.PlotRawData;
        end

        % Button pushed function: LinktoRawXButton
        function LinktoRawXButtonPushed(app, event)
            xlimraw=app.RawAxes.XLim;
            app.SampleRegionsAxes.XLim=xlimraw;  %
            xlimprocessed=xlimraw/app.XrawProcRatio;
            app.WFLAxes.XLim=xlimprocessed;
            app.ScaledAxes.XLim=xlimprocessed;
            app.D5Axes.XLim=xlimprocessed;
            app.ASFAxes.XLim=xlimprocessed;
            app.HighLowAxes.XLim=xlimprocessed;
            app.TwitchLengthAxes.XLim=xlimprocessed;
        end

        % Button pushed function: ScaleButton
        function ScaleButtonPushed(app, event)
            app.CalcWFL();
            hpwfl=plot(app.WFLAxes,app.WFL);
            hpwfl.Color=[.7,.7,.7];
            hpwfl.Marker='.';
            hpwfl.MarkerSize=10;
            hpwfl.MarkerEdgeColor='b';
            hpwfl.MarkerFaceColor='b';
            %
            yline(app.WFLAxes,app.CommandOffsetEditField.Value,'g');
            yline(app.WFLAxes,255,'r');
            % plot command
            %            app.WFLAxes.YLim=[0 500];
            app.datapointsprocessed=floor((app.datapointsraw-1)/app.SyncEditField.Value); %subtract 1 because of deltas
            app.XrawProcRatio=app.datapointsraw/app.datapointsprocessed;% ratio of raw to processed data points
            app.CalcScaled;
            plot(app.ScaledAxes,app.Scaled);
            yline(app.ScaledAxes,255,'r');
            yline(app.ScaledAxes,0,'r');
            
        end

        % Button pushed function: AutoRawButton
        function AutoRawButtonPushed(app, event)
            app.RawAxes.YLimMode='auto';
        end

        % Button pushed function: AutoWFLButton
        function AutoWFLButtonPushed(app, event)
            app.WFLAxes.YLimMode='auto';
        end

        % Button pushed function: AutoCommandButton
        function AutoCommandButtonPushed(app, event)
            app.ScaledAxes.YLimMode='auto';
        end

        % Button pushed function: AutoD5Button
        function AutoD5ButtonPushed(app, event)
            app.D5Axes.YLimMode='auto';
        end

        % Button pushed function: AutoASFButton
        function AutoASFButtonPushed(app, event)
            app.ASFAxes.YLim=[0 255];
        end

        % Selection change function: GraphTabGroup
        function GraphTabGroupSelectionChanged(app, event)
            selectedTab = app.GraphTabGroup.SelectedTab;
            tabnum=find(app.tabsg==selectedTab);
            app.ControlTabGroup.SelectedTab = app.tabsc(tabnum);
        end

        % Selection change function: ControlTabGroup
        function ControlTabGroupSelectionChanged(app, event)
            selectedTab = app.ControlTabGroup.SelectedTab;
            tabnum=find(app.tabsc==selectedTab);
            app.GraphTabGroup.SelectedTab = app.tabsg(tabnum);
        end

        % Value changed function: D5ThreshSlider
        function D5ThreshSliderValueChanged(app, event)
            value = app.D5ThreshSlider.Value;
            app.D5Threshold.Value=value;
            app.D5Axes.Children(2).Value=value;
            app.D5Axes.Children(1).Value=value*-1;
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            
            % plot D5 calculations
            app.CalcD5;
            plot(app.D5Axes,app.CommandD5);
            yline(app.D5Axes,app.D5Threshold.Value,'r');
            yline(app.D5Axes,app.D5Threshold.Value*-1,'r');
            maxD5=max(app.CommandD5);
            app.D5ThreshSlider.Limits=[0,maxD5];
            
            %plot ASF calculations
            app.CommandASF=app.CalcASF(app.Scaled);
            hpasf=plot(app.ASFAxes,app.CommandASF);
            hpasf.Color=[.7,.7,.7];
            hpasf.Marker='.';
            hpasf.MarkerSize=10;
            hpasf.MarkerEdgeColor='b';
            hpasf.MarkerFaceColor='b';
            %
            yline(app.WFLAxes,app.CommandOffsetEditField.Value,'g');
            yline(app.WFLAxes,255,'r');
            
            % logic
            app.CalcHighLow;
            hphl=plot(app.HighLowAxes,app.High);
            hphl.Color=[.7,.7,.7];
            hphl.Marker='.';
            hphl.MarkerSize=10;
            hphl.MarkerEdgeColor='b';
            hphl.MarkerFaceColor='b';
            hptw=plot(app.TwitchLengthAxes,app.HLCount);
            hptw.Color=[.7,.7,.7];
            hptw.Marker='.';
            hptw.MarkerSize=10;
            hptw.MarkerEdgeColor='b';
            hptw.MarkerFaceColor='b';
        end

        % Button pushed function: GetFileButton
        function GetFileButtonPushed(app, event)
            [fn, pn]=uigetfile('*.*','Choose raw EMG file',app.LogFolder);
            app.LogFolder=pn;
            app.LogFileName=fullfile(pn, fn);
            app.LFileID=fopen(app.LogFileName);
            app.PlotRawData();
            app.RawAxes.Title.Interpreter='none';
            app.RawAxes.Title.String=sprintf('Raw %s',fn);
            figure(app.PlotEMGFigure); % Restore focus to this window
            app.datapointsprocessed=floor((app.datapointsraw-1)/app.SyncEditField.Value); %subtract 1 because of deltas
            app.XrawProcRatio=app.datapointsraw/app.datapointsprocessed;% ratio of raw to processed data points
        end

        % Button pushed function: AutoWFL
        function AutoWFLButtonPushed2(app, event)
            % calculate the WFLraw for the entire rawdata array
            targetwflrange=140; %later make this a paramter
            targetscaledrange=420; %want majority of points above 255
            targetgain=round(targetscaledrange/targetwflrange,1);
            relaxbuffer=25;
            app.WFLraw=app.CalcWFLg(1); % get WFL with gain=1
            ignore=round(app.OffsetEditField.Value/app.SyncEditField.Value,0); %points to ignore after transitions
            app.cuepointsprocessed=zeros(1,app.datapointsprocessed); % arrays equal to size of processed array
             icontract(1:app.datapointsprocessed)=false;
              irelax(1:app.datapointsprocessed)=false;
            for i=1:app.datapointsprocessed
                            % time scales are different based upoon app.XrawProcRatio
                j=((i-1)*app.SyncEditField.Value)+1;            
                app.cuepointsprocessed(i)=app.cuedata(j); % for now use time at beginning of WFL region
                if i>ignore
                     % separate the contract and relax regions, omitting a transition region betwee each.
                    icontract(i)=(app.cuepointsprocessed(i)==2 && app.cuepointsprocessed(i-ignore)==2); % this is 
                    irelax(i)=(app.cuepointsprocessed(i)==1 && app.cuepointsprocessed(i-ignore)==1);
%                 else
%                     icontract(i)=false;
%                     irelax(i)=false;
                end
            end
            app.contractAll=app.WFLraw(icontract);
            app.relaxAll=app.WFLraw(irelax);
           medcontract=median(app.contractAll);
           medrelax=median(app.relaxAll);
           meddiff=medcontract-medrelax;
           newWFLgain=targetwflrange/meddiff;
           %need to test whether newWFLgain is within range of 0-100 and
           %give an error if not 
           if newWFLgain>100
               newWFLgain=100;
           end
           app.WFLGainEditField.Value=round(newWFLgain,0); %need it to be integer
           app.CommandGainEditField.Value=targetgain;
% offset needs to be 
           app.CommandOffsetEditField.Value=round(app.WFLGainEditField.Value*medrelax+(relaxbuffer/targetgain),0);
            % perform magic on the contract region to give the WFL gain that produces a range of 140 adu
            % between contract and relax region
            % calculate the gain that will produce a range of 280 adu
            %  determine the offset (before gain) that would produce range from -25 to +255 (to have buffer at lower level
            %
            %
            %
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PlotEMGFigure = uifigure('Visible', 'off');
            app.PlotEMGFigure.Name = 'DisplayRawEMGCue';
            app.RawPanel = uipanel(app.PlotEMGFigure);
            app.RawPanel.Title = 'Raw';
            app.SampleRegionsAxes = uiaxes(app.RawPanel);
            title(app.SampleRegionsAxes, 'Cue Regions')
            ylabel(app.SampleRegionsAxes, 'Y')
            app.SampleRegionsAxes.YLim = [0 1.5];
            app.SampleRegionsAxes.YTick = [0 0.1 1.5];
            app.SampleRegionsAxes.YTickLabel = {'0'; '0.1'; '1.5'};
            app.RawAxes = uiaxes(app.RawPanel);
            title(app.RawAxes, 'Raw EMG')
            ylabel(app.RawAxes, 'Y')
            app.AutoRawButton = uibutton(app.RawPanel, 'push');
            app.AutoRawButton.ButtonPushedFcn = createCallbackFcn(app, @AutoRawButtonPushed, true);
            app.AutoRawButton.Text = 'Y';
            app.LinktoRawXButton = uibutton(app.RawPanel, 'push');
            app.LinktoRawXButton.ButtonPushedFcn = createCallbackFcn(app, @LinktoRawXButtonPushed, true);
            app.LinktoRawXButton.Text = 'Link to Raw X';
            app.ProcessedPanel = uipanel(app.PlotEMGFigure);
            app.ProcessedPanel.Title = 'Processed';
            app.WFLAxes = uiaxes(app.ProcessedPanel);
            title(app.WFLAxes, 'WFL')
            ylabel(app.WFLAxes, 'Y')
            app.ScaledAxes = uiaxes(app.ProcessedPanel);
            title(app.ScaledAxes, 'Scaled')
            ylabel(app.ScaledAxes, 'Y')
            app.AutoWFLButton = uibutton(app.ProcessedPanel, 'push');
            app.AutoWFLButton.ButtonPushedFcn = createCallbackFcn(app, @AutoWFLButtonPushed, true);
            app.AutoWFLButton.Text = 'Y';
            app.AutoCommandButton = uibutton(app.ProcessedPanel, 'push');
            app.AutoCommandButton.ButtonPushedFcn = createCallbackFcn(app, @AutoCommandButtonPushed, true);
            app.AutoCommandButton.Text = 'Y';
            app.Panel = uipanel(app.PlotEMGFigure);
            app.Panel.Title = 'Panel';
            app.ReplotButton = uibutton(app.Panel, 'push');
            app.ReplotButton.ButtonPushedFcn = createCallbackFcn(app, @ReplotButtonPushed, true);
            app.ReplotButton.Text = 'Replot';
            app.GetFileButton = uibutton(app.Panel, 'push');
            app.GetFileButton.ButtonPushedFcn = createCallbackFcn(app, @GetFileButtonPushed, true);
            app.GetFileButton.Tooltip = {'still need to change buttons sequnetially in color to make it easy to walk through'};
            app.GetFileButton.Text = 'Get File';
            app.SkipNaNSwitchLabel = uilabel(app.Panel);
            app.SkipNaNSwitchLabel.Text = 'Skip NaN';
            app.SkipNaNSwitch = uiswitch(app.Panel, 'slider');
            app.SkipNaNSwitch.ItemsData = {'0', '1'};
            app.SkipNaNSwitch.Value = '1';
            app.OffsetmsLabel = uilabel(app.Panel);
            app.OffsetmsLabel.Text = 'Offset (ms)';
            app.OffsetEditField = uieditfield(app.Panel, 'numeric');
            app.OffsetEditField.Value = 500;
            app.AutoWFL = uibutton(app.Panel, 'push');
            app.AutoWFL.ButtonPushedFcn = createCallbackFcn(app, @AutoWFLButtonPushed2, true);
            app.AutoWFL.Tooltip = {'Based on current hardware gain and sample regions, calcualte best WFL gain and Software gain/offset'};
            app.AutoWFL.Text = {'Auto'; 'Gains/Offsets'};
            app.GraphTabGroup = uitabgroup(app.PlotEMGFigure);
            app.GraphTabGroup.SelectionChangedFcn = createCallbackFcn(app, @GraphTabGroupSelectionChanged, true);
            app.PropTab = uitab(app.GraphTabGroup);
            app.PropTab.Title = 'Prop';
            app.ASFAxes = uiaxes(app.PropTab);
            title(app.ASFAxes, 'ASF Command')
            xlabel(app.ASFAxes, 'X')
            ylabel(app.ASFAxes, 'Y')
            app.AutoASFButton = uibutton(app.PropTab, 'push');
            app.AutoASFButton.ButtonPushedFcn = createCallbackFcn(app, @AutoASFButtonPushed, true);
            app.AutoASFButton.Text = 'Y';
            app.LogicalTab = uitab(app.GraphTabGroup);
            app.LogicalTab.Title = 'Logical';
            app.D5Axes = uiaxes(app.LogicalTab);
            title(app.D5Axes, 'D5 calculations')
            ylabel(app.D5Axes, 'Y')
            app.HighLowAxes = uiaxes(app.LogicalTab);
            title(app.HighLowAxes, 'Low and High')
            ylabel(app.HighLowAxes, 'Y')
            app.TwitchLengthAxes = uiaxes(app.LogicalTab);
            title(app.TwitchLengthAxes, 'TwitchLength')
            ylabel(app.TwitchLengthAxes, 'Y')
            app.AutoD5Button = uibutton(app.LogicalTab, 'push');
            app.AutoD5Button.ButtonPushedFcn = createCallbackFcn(app, @AutoD5ButtonPushed, true);
            app.AutoD5Button.Text = 'Y';
            app.AutoHLButton = uibutton(app.LogicalTab, 'push');
            app.AutoHLButton.Text = 'Y';
            app.AutoTLButton = uibutton(app.LogicalTab, 'push');
            app.AutoTLButton.Text = 'Y';
            app.ControlTabGroup = uitabgroup(app.PlotEMGFigure);
            app.ControlTabGroup.SelectionChangedFcn = createCallbackFcn(app, @ControlTabGroupSelectionChanged, true);
            app.ControlPropTab = uitab(app.ControlTabGroup);
            app.ControlPropTab.Title = 'Prop';
            app.MaybeaddASFoptionsLabel = uilabel(app.ControlPropTab);
            app.MaybeaddASFoptionsLabel.Text = 'Maybe add ASF options';
            app.ControlLogicalTab = uitab(app.ControlTabGroup);
            app.ControlLogicalTab.Title = 'Logical';
            app.D5ThreshLabel = uilabel(app.ControlLogicalTab);
            app.D5ThreshLabel.Text = 'D5 Thresh';
            app.D5Threshold = uieditfield(app.ControlLogicalTab, 'numeric');
            app.D5Threshold.Limits = [1 3000];
            app.D5Threshold.RoundFractionalValues = 'on';
            app.D5Threshold.Value = 200;
            app.HLTimeoutLabel = uilabel(app.ControlLogicalTab);
            app.HLTimeoutLabel.Text = 'HL Timeout';
            app.HLtimeout = uieditfield(app.ControlLogicalTab, 'numeric');
            app.HLtimeout.Limits = [1 3000];
            app.HLtimeout.RoundFractionalValues = 'on';
            app.HLtimeout.Value = 16;
            app.D5ThreshSliderLabel = uilabel(app.ControlLogicalTab);
            app.D5ThreshSliderLabel.Text = 'D5Thresh';
            app.D5ThreshSlider = uislider(app.ControlLogicalTab);
            app.D5ThreshSlider.Limits = [0 2000];
            app.D5ThreshSlider.ValueChangedFcn = createCallbackFcn(app, @D5ThreshSliderValueChanged, true);
            app.D5ThreshSlider.Value = 300;
            app.Tab = uitab(app.ControlTabGroup);
            app.Tab.Title = 'Tab';
            app.ProcessPanel = uipanel(app.PlotEMGFigure);
            app.ProcessPanel.Title = 'Process';
            app.CmdOffsetLabel = uilabel(app.ProcessPanel);
            app.CmdOffsetLabel.Text = 'Cmd Offset';
            app.CommandOffsetEditField = uieditfield(app.ProcessPanel, 'numeric');
            app.CommandOffsetEditField.Limits = [0 255];
            app.CommandOffsetEditField.RoundFractionalValues = 'on';
            app.CommandOffsetEditField.Value = 20;
            app.CmdGainLabel = uilabel(app.ProcessPanel);
            app.CmdGainLabel.Text = 'Cmd Gain';
            app.CommandGainEditField = uieditfield(app.ProcessPanel, 'numeric');
            app.CommandGainEditField.Limits = [1 255];
            app.CommandGainEditField.ValueDisplayFormat = '%3.1f';
            app.CommandGainEditField.Value = 2;
            app.ScaleButton = uibutton(app.ProcessPanel, 'push');
            app.ScaleButton.ButtonPushedFcn = createCallbackFcn(app, @ScaleButtonPushed, true);
            app.ScaleButton.Text = 'Scale';
            app.WFLGainEditFieldLabel = uilabel(app.ProcessPanel);
            app.WFLGainEditFieldLabel.Text = 'WFL Gain';
            app.WFLGainEditField = uieditfield(app.ProcessPanel, 'numeric');
            app.WFLGainEditField.Limits = [1 100];
            app.WFLGainEditField.RoundFractionalValues = 'on';
            app.WFLGainEditField.Value = 10;
            app.ProcessButton = uibutton(app.ProcessPanel, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.SyncEditFieldLabel = uilabel(app.ProcessPanel);
            app.SyncEditFieldLabel.Text = 'Sync (ms)';
            app.SyncEditField = uieditfield(app.ProcessPanel, 'numeric');
            app.SyncEditField.Limits = [5 120];
            app.SyncEditField.RoundFractionalValues = 'on';
            app.SyncEditField.Value = 60;
            app.UnblankmsLabel = uilabel(app.ProcessPanel);
            app.UnblankmsLabel.Text = 'Unblank (ms)';
            app.UnblankEditField = uieditfield(app.ProcessPanel, 'numeric');
            app.UnblankEditField.Limits = [10 80];
            app.UnblankEditField.RoundFractionalValues = 'on';
            app.UnblankEditField.Value = 15;
            % Show the figure after all components are created
            app.PlotEMGFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = emgDisplayRawCue(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PlotEMGFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlotEMGFigure)
        end
    end
end