classdef emgRecordProcessedTab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        SaveAsMenu                      matlab.ui.container.Menu
        chEditFieldB                    matlab.ui.control.NumericEditField
        chEditField_2Label              matlab.ui.control.Label
        chEditFieldA                    matlab.ui.control.NumericEditField
        chEditFieldLabel                matlab.ui.control.Label
        ChannelBLabel                   matlab.ui.control.Label
        ChannelALabel                   matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        XButton                         matlab.ui.control.Button
        StatusPanel                     matlab.ui.container.Panel
        ToggleButton                    matlab.ui.control.Button
        Pattern                         matlab.ui.control.NumericEditField
        PatternLabel                    matlab.ui.control.Label
        Lock                            matlab.ui.control.Lamp
        LockLabel                       matlab.ui.control.Label
        ptLED                           matlab.ui.control.Lamp
        PtLabel                         matlab.ui.control.Label
        netLED                          matlab.ui.control.Lamp
        NetLabel                        matlab.ui.control.Label
        ViewEMGButton                   matlab.ui.control.StateButton
        ParamFileEditField              matlab.ui.control.EditField
        ParamFileEditFieldLabel         matlab.ui.control.Label
        ClearMessageButton              matlab.ui.control.Button
        Messages                        matlab.ui.control.TextArea
        ParGroup                        matlab.ui.container.TabGroup
        LogTab                          matlab.ui.container.Tab
        UserIDEditField                 matlab.ui.control.EditField
        ifEMGnotdisplayedcheckScriptOrderMESSyncTimingPDOsetupLabel  matlab.ui.control.Label
        CommentsTextAreaLabel           matlab.ui.control.Label
        CommentsTextArea                matlab.ui.control.TextArea
        CommentFileNameEditField        matlab.ui.control.EditField
        LogFileNameEditField            matlab.ui.control.EditField
        FolderEditField                 matlab.ui.control.EditField
        FcloseButton                    matlab.ui.control.Button
        NewLogFile                      matlab.ui.control.Button
        LogtofileButton                 matlab.ui.control.StateButton
        ParameterTab                    matlab.ui.container.Tab
        GainSetBDropDown                matlab.ui.control.DropDown
        GainSetBDropDownLabel           matlab.ui.control.Label
        GainSetADropDown                matlab.ui.control.DropDown
        GainSetADropDownLabel           matlab.ui.control.Label
        ScalePanel                      matlab.ui.container.Panel
        LowerTh                         matlab.ui.control.NumericEditField
        PoffsetLabel_3                  matlab.ui.control.Label
        UpperTh                         matlab.ui.control.NumericEditField
        PgainLabel_3                    matlab.ui.control.Label
        Pgain                           matlab.ui.control.NumericEditField
        PgainLabel                      matlab.ui.control.Label
        Poffset                         matlab.ui.control.NumericEditField
        PoffsetLabel                    matlab.ui.control.Label
        UpdateButton_4                  matlab.ui.control.Button
        SaveBPODButton                  matlab.ui.control.Button
        ScriptsPanel                    matlab.ui.container.Panel
        ActionGo                        matlab.ui.control.Button
        Script                          matlab.ui.control.DropDown
        PlotLimitsPanel                 matlab.ui.container.Panel
        ToolsButton                     matlab.ui.control.StateButton
        setYButton                      matlab.ui.control.Button
        setXButton                      matlab.ui.control.Button
        BlueovalssendparameterstoODinPMandRMLabel  matlab.ui.control.Label
        ObjectDictionaryPanel           matlab.ui.container.Panel
        SavePMODButton                  matlab.ui.control.Button
        ODGOButton                      matlab.ui.control.Button
        ModulesDropDown                 matlab.ui.control.DropDown
        LogicParametersPanel            matlab.ui.container.Panel
        UpdateButton_3                  matlab.ui.control.Button
        RestoreLocksButton              matlab.ui.control.Button
        DisableLocksButton              matlab.ui.control.Button
        Tw2Lock                         matlab.ui.control.NumericEditField
        Tw2LockLabel                    matlab.ui.control.Label
        LockCmdThresh                   matlab.ui.control.NumericEditField
        LockCmdThLabel                  matlab.ui.control.Label
        HoldCyclesLock                  matlab.ui.control.NumericEditField
        HoldCyclesLabel                 matlab.ui.control.Label
        TwitchParametersPanel_2         matlab.ui.container.Panel
        UpdateButton_6                  matlab.ui.control.Button
        DisableDouble_2                 matlab.ui.control.Button
        EnableDoublet_2                 matlab.ui.control.Button
        D5high_2                        matlab.ui.control.NumericEditField
        D5highLabel_2                   matlab.ui.control.Label
        Quiet_2                         matlab.ui.control.NumericEditField
        QuietLabel_2                    matlab.ui.control.Label
        TwitchParametersPanel_1         matlab.ui.container.Panel
        UpdateButton_5                  matlab.ui.control.Button
        Quiet                           matlab.ui.control.NumericEditField
        QuietLabel                      matlab.ui.control.Label
        D5high                          matlab.ui.control.NumericEditField
        D5highLabel                     matlab.ui.control.Label
        DisableDouble                   matlab.ui.control.Button
        EnableDoublet                   matlab.ui.control.Button
        EMGWFLBPanel                    matlab.ui.container.Panel
        UpdateButton_2                  matlab.ui.control.Button
        WFLgain_2                       matlab.ui.control.NumericEditField
        WFLgainLabel_2                  matlab.ui.control.Label
        Depth_2                         matlab.ui.control.NumericEditField
        DepthLabel_2                    matlab.ui.control.Label
        HWgain_2                        matlab.ui.control.NumericEditField
        HWgainLabel_2                   matlab.ui.control.Label
        EMGWFLAPanel                    matlab.ui.container.Panel
        UpdateButton                    matlab.ui.control.Button
        WFLgain                         matlab.ui.control.NumericEditField
        WFLgainLabel                    matlab.ui.control.Label
        Depth                           matlab.ui.control.NumericEditField
        DepthLabel                      matlab.ui.control.Label
        HWgain                          matlab.ui.control.NumericEditField
        HWgainLabel                     matlab.ui.control.Label
        OptionsTab                      matlab.ui.container.Tab
        Label_7                         matlab.ui.control.Label
        N71F5777Label                   matlab.ui.control.Label
        BPEMGChannelConnectionsDropDown  matlab.ui.control.DropDown
        BPEMGChannelConnectionsDropDownLabel  matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        SyncPanel                       matlab.ui.container.Panel
        BPnode                          matlab.ui.control.NumericEditField
        BPnodeLabel                     matlab.ui.control.Label
        UpdateButton_7                  matlab.ui.control.Button
        SyncInt                         matlab.ui.control.NumericEditField
        SyncIntLabel                    matlab.ui.control.Label
        Sync                            matlab.ui.control.NumericEditField
        SyncLabel                       matlab.ui.control.Label
        PMversion405Label               matlab.ui.control.Label
        CommandToggleResetButton        matlab.ui.control.Button
        CommandToggleTestButton         matlab.ui.control.Button
        UpdateButton_8                  matlab.ui.control.Button
        PatternChangeDropDown           matlab.ui.control.DropDown
        PatternChangeDropDownLabel      matlab.ui.control.Label
        CommandToggleDropDown           matlab.ui.control.DropDown
        CommandToggleDropDownLabel      matlab.ui.control.Label
        LockDisablePanel                matlab.ui.container.Panel
        DisableLocks                    matlab.ui.control.NumericEditField
        DisableLocksLabel               matlab.ui.control.Label
        LockDisableTable                matlab.ui.control.Table
        UnLockDoubletChannelDropDown    matlab.ui.control.DropDown
        UnLockDoubletChannelDropDownLabel  matlab.ui.control.Label
        LockTwitchChannelDropDown       matlab.ui.control.DropDown
        LockTwitchChannelDropDownLabel  matlab.ui.control.Label
        StandTab                        matlab.ui.container.Tab
        Label_4                         matlab.ui.control.Label
        StartStandingTransferButton     matlab.ui.control.Button
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        ScalePanel_2                    matlab.ui.container.Panel
        UpdateButton_9                  matlab.ui.control.Button
        Pgain_2                         matlab.ui.control.NumericEditField
        PgainLabel_2                    matlab.ui.control.Label
        Poffset_2                       matlab.ui.control.NumericEditField
        PoffsetLabel_2                  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        TabGroup_2                      matlab.ui.container.TabGroup
        LogicalTab_2                    matlab.ui.container.Tab
        HLAxes_2                        matlab.ui.control.UIAxes
        TwitchAxes_2                    matlab.ui.control.UIAxes
        D5Axes_2                        matlab.ui.control.UIAxes
        ProportionalTab_2               matlab.ui.container.Tab
        ComAxes_2                       matlab.ui.control.UIAxes
        ASFAxes_2                       matlab.ui.control.UIAxes
        TabGroup                        matlab.ui.container.TabGroup
        LogicalTab                      matlab.ui.container.Tab
        HLAxes                          matlab.ui.control.UIAxes
        TwitchAxes                      matlab.ui.control.UIAxes
        D5Axes                          matlab.ui.control.UIAxes
        ProportionalTab                 matlab.ui.container.Tab
        ASFAxes                         matlab.ui.control.UIAxes
        ScaledAxes_2                    matlab.ui.control.UIAxes
        EMGAxes_2                       matlab.ui.control.UIAxes
        ScaledAxes                      matlab.ui.control.UIAxes
        EMGAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        nnp = []; % Description
        LockDisable=[];
        lastLock=0;
        % buffers 1
        plotbuf = [];
        emgbuf = [];
        scaledbuf = [];
        d5buf = [];
        twitchbuf = [];
        hlbuf=[];
        asfbuf=[];
        skipplots=0;
        
        % buffers 2
        plotbuf2 = [];
        emgbuf2 = [];
        scaledbuf2 = [];
        d5buf2 = [];
        twitchbuf2 = [];
        hlbuf2=[];
        asfbuf2=[];
        combuf2=[];
        
        emgTimer = [];
        plotcycle=4; %plot every forth cycle, makeing this 5Hz update
        cycle=0;
        
        emgsamp = 50; % start with 5 seconds, may be too slow
        tdata=[20,17,34,36,32,30];
        tdataboth=[19,16,33,35,31,29;20,17,34,36,32,30]; %two rows
        ODval=double(zeros(1,16));
        useTrunkCommand=false;
        lastcounter=0;
        plotthis=0;
        oldtime=[];
        templockdisable=0; % when I disable, I want to save the old value
        BPsettings=struct('Pgain',2,'Poffset',0,'D5high1',200,'D5high2',200,'Node',9); %structure to pass values back and forth
        bpChanA='3411';
        bpChanB='3511';
        bpSwapAB=false; %may not need this one.
    end
    
    properties (Access = private)
        BPmod=uint8(9);
        updateCount = 1;
        savedSettings = [];
        savedTimeout =[];
        savedPMworInt=[];
        selpath=pwd;% get the current working directory
        LogFileName='LogFileNamedefault.csv'; % default file name
        CommentFileName='CommentFileNamedefault.txt'; % default file name
        FileNameDialog % Dialog to return log and comment and log file full paths
        LFileID=double([]); % File ID for Log File
        CommentFileID=-1; %Comment file ID
        callingFigure=[]; %handle to calling figure
        
        pTw=[];
        pWFL=[]; % WFL plot
        pWFLoff=[]; % WFL offset plot
        pWFLgain=[]; % WFL gain plot
        
        pWFL2=[]; % WFL plot
        pWFLoff2=[]; % WFL offset plot
        pWFLgain2=[]; % WFL gain plot
        
        pD51=[]; % D5 plot 1
        pD5high1=[]; % D5 high for yline of D5 plot 1
        pD5low1=[]; % D5 low for yline of D5 plot 1
        pD52=[];  % D5 plot 2
        pD5high2=[]; % D5 high for yline of D5 plot 2
        pD5low2=[]; % D5 low for yline of D5 plot 2
        defaultXlimits=[0,50]; %set in one place to make easier to change
        
        emgFilename=[]; % File to store EMG parameters
        emgParameters=[];% variable to store the EMG parameters when passing back and forth to file
        pathname=[]; % pathname for parameters

        % Code for auto userID open
         userFolders=struct;  % userfiles, code, user, config, data, log, where code is file prefix
          defaultSuffix=''; %before all the date/time information is added
         defaultName='P00_'; %if userFolders is not valid use this
         defaultPath=pwd;
    end
    
    methods (Access = public)
        % the next step is to record the data.
        % create a window to enter the name to log the data
        % in that window select a folder and user id, then auto create the
        % name


        function updateEMG(app, obj, event)
            resp = app.nnp.read(7, '1F53', 26,50, 'uint8');
            if(length(resp)==50) %valid packet
                % Net On is resp(14)
                % Node state is resp(13)
                % If I want Trunk command I need to read subindex 35
                % instead of 32.  which is 10 instead of 7 for this vector
                % BOOKMARK1
                if app.useTrunkCommand
                    app.ODval([1:7,9:10,12:16])=double(resp([1,21,18,31,22,19,32,33,39,34,40,10,17,23]));
                else
                    app.ODval([1:7,9:10,12:16])=double(resp([1,21,18,31,22,19,32,33,39,34,40,7,17,23]));
                end
                app.ODval(8)=double(typecast(resp(35:36),'int16')); %int16
                app.ODval(11)=double(typecast(resp(37:38),'int16'));
                netStatus=double(resp(14));
                nodeState=double(resp(13));
                % here I should output the difference between the current
                % and last counter.  If the difference is negative (i.e.
                % rolled over from 255 to 0) then add 256 to get the real
                % difference
                %                 delta=app.ODval(1)-app.lastcounter;
                %                 if delta<0
                %                     delta=delta+256;
                %                 end
                % here I use data as the set of indices to use for generating the
                % arrays for graphing.
                
                if app.ODval(1)~=app.lastcounter % different values
                    
                    
                    % plots 1  tdata=  19,16,33,35,31,29;
                    newemg=app.ODval(2);
                    newscaled=app.ODval(3);
                    newd5=app.ODval(8);
                    newtwitch=app.ODval(10);
                    newhl=app.ODval(9);
                    newasf=app.ODval(4);
                    
                    %keep all buffers up to date, even if not plotting
                    %
                    app.emgbuf(1:end-1) = app.emgbuf(2:end);
                    app.emgbuf(end)=newemg;
                    
                    app.scaledbuf(1:end-1) = app.scaledbuf(2:end);
                    app.scaledbuf(end)=newscaled;
                    
                    app.d5buf(1:end-1) = app.d5buf(2:end);
                    app.d5buf(end)=newd5;
                    
                    app.twitchbuf(1:end-1) = app.twitchbuf(2:end);
                    app.twitchbuf(end)=newtwitch;
                    
                    app.hlbuf(1:end-1) = app.hlbuf(2:end);
                    app.hlbuf(end)=newhl;
                    %
                    app.asfbuf(1:end-1) = app.asfbuf(2:end);
                    app.asfbuf(end)=newasf;
                    
                    if app.plotthis==1
                        %                     app.EMGAxes.Children.YData = app.emgbuf;
                        app.pWFL.YData = app.emgbuf;
                        app.ScaledAxes.Children.YData = app.scaledbuf;

                            if app.TabGroup.SelectedTab==app.LogicalTab
                                app.pD51.YData = app.d5buf;
                                %app.TwitchAxes.Children.YData = app.twitchbuf;
                                app.pTw.YData = app.twitchbuf;
                                app.HLAxes.Children.YData = app.hlbuf;

                            elseif app.TabGroup.SelectedTab==app.ProportionalTab
                                app.ASFAxes.Children.YData = app.asfbuf;
                            end

                    end %plots 1
                    
                    % plots 2, tdata = 20,17,34,36,32,30
                    % buffers 2
                    %         plotbuf2 = [];
                    %         emgbuf2 = [];
                    %         scaledbuf2 = [];
                    %         d5buf2 = [];
                    %         twitchbuf2 = [];
                    %         hlbuf2=[];
                    %         asfbuf2=[];
                    
                    newemg=app.ODval(5); % WFL
                    newscaled=app.ODval(6);
                    newd5=app.ODval(11);
                    newtwitch=app.ODval(13);
                    newhl=app.ODval(12);
                    newasf=app.ODval(7);

                    newcom=app.ODval(14);  %Hand Command

                    %keep all buffers up to date, even if not plotting
                    %
                    app.emgbuf2(1:end-1) = app.emgbuf2(2:end);
                    app.emgbuf2(end)=newemg;
                    
                    app.scaledbuf2(1:end-1) = app.scaledbuf2(2:end);
                    app.scaledbuf2(end)=newscaled;
                    
                    app.d5buf2(1:end-1) = app.d5buf2(2:end);
                    app.d5buf2(end)=newd5;
                    
                    app.twitchbuf2(1:end-1) = app.twitchbuf2(2:end);
                    app.twitchbuf2(end)=newtwitch;
                    
                    app.hlbuf2(1:end-1) = app.hlbuf2(2:end);
                    app.hlbuf2(end)=newhl;
                    %
                    app.asfbuf2(1:end-1) = app.asfbuf2(2:end);
                    app.asfbuf2(end)=newasf;
                    
                    app.combuf2(1:end-1) = app.combuf2(2:end);
                    app.combuf2(end)=newcom;
                    
                    if app.plotthis==0  % plot group 2 plot on alternate cycle
                        %                     app.EMGAxes.Children.YData = app.emgbuf;
                        app.pWFL2.YData = app.emgbuf2;
                        app.ScaledAxes_2.Children.YData = app.scaledbuf2;
                        if app.skipplots==0
                            if app.TabGroup_2.SelectedTab==app.LogicalTab_2
                                app.pD52.YData = app.d5buf2;
                                app.TwitchAxes_2.Children.YData = app.twitchbuf2;
                                app.HLAxes_2.Children.YData = app.hlbuf2;

                            elseif app.TabGroup_2.SelectedTab==app.ProportionalTab_2
                                app.ASFAxes_2.Children.YData = app.asfbuf2;
                                app.ComAxes_2.Children.YData = app.combuf2;
                                if app.ODval(15)~=app.lastLock
                                    switch app.ODval(15)
                                        case 0
                                            app.Lock.Color=[.9, .9, .9]; % off = grey
                                            app.ComAxes_2.Color = [1 1 1]; % white
                                        case 1
                                            app.Lock.Color=[1, 1, 0]; % transition yellow
                                            app.ComAxes_2.Color = [1 1 .9]; % change color during transition
                                        case 2
                                            app.Lock.Color=[0, 1,0]; % on = green
                                            app.ComAxes_2.Color = [0.900 1 0.900];
                                    end
                                    app.lastLock=app.ODval(15);
                                end
                            end
                        end %skippplots
                    end %plots 2
                    
                    app.Pattern.Value=app.ODval(16); % Hand pattern
                    
                    % Update Lock LED based upon value.  0=unlocked, 1=transition, 2=locked
                   
                    switch app.ODval(15)
                        case 0
                            app.Lock.Color=[.9, .9, .9]; % off = grey
                        case 1
                            app.Lock.Color=[1, 1, 0]; % transition yellow
                        case 2
                            app.Lock.Color=[0, 1,0]; % on = green
                    end
                    % Do I want to update every cycle or just when changed?  Is there a timing
                    % cost?
                    if app.plotthis==1
                        app.plotthis=0;
                    else
                        app.plotthis=1; % alternate which plots updated
                    end
                    % should be able to replace this with x=emgtimer.AveragePeriod
                    %Average time in seconds between TimerFcn executions since the timer started. Value is NaN until timer executes two timer
                    %callbacks.
                    %                     newtime=now();
                    %                     app.ms.Value=(newtime-app.oldtime)*86400000;
                    %                     app.oldtime=newtime;
                    if app.LogtofileButton.Value==1 % log to file
                        % Log app.ODval, which is array of 46 elements - may
                        % need to adjust the formatting
                        fprintf(app.LFileID,'%2.2f, ',app.ODval(1:15)); % print without the carriage return
                        fprintf(app.LFileID,'%2.2f\n',app.ODval(16)'); % print carriage return
                    end
                end
                % set LED colors for Net and PT mode
                % network is netStatus
                % Mode is nodeState
                switch netStatus
                    case 0
                        app.netLED.Color=[.9, .9, .9]; % off = grey
                    case 1
                        app.netLED.Color= [0, 1,0]; % on = green
                end
                
                switch nodeState
                    case 5
                        app.ptLED.Color= [0, 1,0]; % on = green
                    otherwise
                        app.ptLED.Color=[.9, .9, .9]; % off = grey
                end
                
                app.lastcounter=app.ODval(1);
                %                 app.TopMessage(''; % not sure I want to clear so fast
            else
                app.TopMessage('Error reading PM EMG data'); % how to add a carriage return?
                %
            end
        end
        
        function startEMG(app)
            app.oldtime=now();
            if app.nnp.getNetworkStatus()==1  % if network is on, then proceed, otherwise send message
                % check if in patient mode
                app.saveRadio();
                % for max bandwidth, we need to eliminate radio retries, shorten timeouts, and turn of WOR
                app.nnp.setRadio('timeout', 200, 'retries', 5');
                app.nnp.worOff();
                app.disableGraphInteractions(); % disable while running
                start(app.emgTimer); %not log data to file
            else
                app.TopMessage('Network is not ON');
                app.ViewEMGButton.Value=false;
                
            end
            % Here I could disable the interactions and reenable them when
            % finished recording.
        end
        
        function stopEMG(app)
            %  app.enableAll('on');
            stop(app.emgTimer);
            app.restoreRadio();
            app.enableGraphInteractions();
            %turn LEDs off when not running for now
            app.netLED.Color=[.9, .9, .9]; % off = grey
            app.ptLED.Color=[.9, .9, .9]; % off = grey
            
        end
        
        function setNetworkOn(app)
            if ~app.nnp.enterWaiting()
                msgbox('could not enter waiting on PM');
                return;
            end
            
            if ~app.nnp.networkOn()
                msgbox('could not turn on network');
                return;
            end
            
            pause(0.2)
            while app.nnp.getNetworkStatus()~=1
                resp = questdlg('Could not confirm network status, Retry?');
                if isequal(resp, 'No')
                    return;
                end
            end
        end
        
        function success = runScript(app,script,inmessage)
            resp=app.nnp.nmt(7,'8D',script);
            success = (resp == hex2dec('8D'));
            if (resp == hex2dec('8D'))
                message=inmessage;
            else
                message=[inmessage, ' failed'];
            end
            app.TopMessage(message);
            
        end
        
        function success = startADL(app,pat)
            % if either message fails, then consider both failed
            resp=app.nnp.write(7,'1F53',22,uint8(pat),'uint8'); % immediate pattern set
            success=(resp==0);
            if resp==0
                resp2=app.nnp.write(7,'1F53',4,uint8(5),'uint8');
                if resp2==0
                    message=['Start ADL',num2str(pat)];
                else
                    message=['Start ADL',num2str(pat),' failed'];
                end
            else
                message=['Start ADL',num2str(pat),' failed'];
            end
            app.TopMessage(message);
        end
        
        function success = stopADL(app)
            resp=app.nnp.write(7,'1F53',4,uint8(9),'uint8');
            success=(resp==0);
            if resp==0
                message='Stop ADL';
            else
                message=['Stop ADL',' failed'];
            end
            app.TopMessage(message);
        end


        function NewFileNames(app,user,folder,logname,commentname)
            % In the following I update fields, but I could just use
            % properies and not display these.
            % this looks a little convalueted, but is intended to work with
            % either full or partial name
            app.UserIDEditField.Value=user;
            app.FolderEditField.Value=folder;
            [~,b,c]=fileparts(logname);
            fname=[b,c];
            app.LogFileNameEditField.Value=fname;
            app.LogFileName=fullfile(folder,fname);
            
            [~,b,c]=fileparts(commentname);
            fname=[b,c];
            app.CommentFileNameEditField.Value=fname;
            app.CommentFileName=fullfile(folder,fname);
            
            % This function is called if the dialog box is not cancelled.
            if ~isempty(app.LFileID) % cannot use fopen with empty variable
                if ~isempty(fopen(app.LFileID)) % check whether file is already open, 1 means not open
                    fclose(app.LFileID); % close this file before opening another
                end
            end
            app.LFileID=fopen(app.LogFileName,'a'); % Open in append mode
            % Since file is newly opened, then write header.
            %             fprintf('LfileID %d\n',app.LFileID);
            header=app.makeHeader;
            fprintf(app.LFileID,header);
            
        end
        function refreshWFLylines(app)
            if app.useTrunkCommand
                offset = app.Poffset_2.Value;
                upper = (255/app.Pgain_2.Value)+offset;
            else
%                 offset = app.Poffset.Value;
%                 upper = (255/app.Pgain.Value)+offset;
                 offset = app.LowerTh.Value;
                 upper = app.UpperTh.Value;
            end 
            app.pWFLoff.Value=offset;
            app.pWFLgain.Value=upper;
            app.pWFLoff2.Value=offset;
            app.pWFLgain2.Value=upper;
        end
        
        function refreshD5ylines(app)
            high=app.D5high.Value;
            low=app.D5high.Value*-1;
            app.pD5high1.Value=high;
            app.pD5low1.Value=low;
            high=app.D5high_2.Value;
            low=app.D5high_2.Value*-1;
            app.pD5high2.Value=high;
            app.pD5low2.Value=low;
        end
        
        function writePmParameters(app)
            % write the power module parameters to the PM
            %             Need to write to PM twice.  One block of 16, one of 4
            % first READ from read PM to fill buffer
            resp = app.nnp.read(7, '1F57', 1,16, 'uint8'); % need first 16 values
            if length(resp)==16 % all read
                pmWrite=double(resp); %values in edit boxes need double precision
                % fill appropriate elements of pmWrite
                % Added LowerTh and UpperTh - 
                % update these first before writing
                app.Poffset.Value=app.LowerTh.Value;
                app.Pgain.Value=255/(app.UpperTh.Value-app.LowerTh.Value);

                pmWrite(1)=app.Poffset.Value;
                pmWrite(2)=app.Pgain.Value*10;
                pmWrite(3)=app.Poffset_2.Value;
                pmWrite(4)=app.Pgain_2.Value*10;
                pmWrite(5)=app.D5high.Value/10;
                pmWrite(6)=app.Quiet.Value;
                pmWrite(7)=app.D5high_2.Value/10;
                pmWrite(8)=app.Quiet_2.Value;
                pmWrite(11)=app.Tw2Lock.Value;
                pmWrite(12)= app.LockCmdThresh.Value;
                pmWrite(13)=app.HoldCyclesLock.Value;
                pmWrite(16)=app.DisableLocks.Value;
                % Need to fill table here
                app.DisableLocksValueChanged();
                % write back to PM
                resp = app.nnp.write(7, '1F57', 1, uint8(pmWrite),'uint8',16); % need first 16 values
                app.writeErrorMsg(resp,'PM');
                app.TopMessage('Wrote PM Parameters');
            else
                app.TopMessage('Error reading PM');
            end
            resp = app.nnp.read(7, '1F57', 103,5, 'uint8'); % just 5 elements
            if length(resp)==5 % all read
                pmWrite=double(resp); %values in edit boxes need double precision
                if app.UnLockDoubletChannelDropDown.Value == 'EMG-A'
                    pmWrite(1)=7;
                    pmWrite(3)=84;
                else
                    pmWrite(1)=8;
                    pmWrite(3)=85;
                end
                
                if app.LockTwitchChannelDropDown.Value == 'EMG-A'
                    pmWrite(2)=84;
                else
                    pmWrite(2)=85;
                end
%                pmWrite(4)=app.AllowPatternChange.Value;
                pmWrite(4)=app.PatternChangeDropDown.Value;
                pmWrite(5)=app.CommandToggleDropDown.Value;
                app.CommandToggleDropDownValueChanged();
                % need to add this later
                % pmWrite(5)=app.enableCommandToggle  % 0=none, 1=button only, 2=include twitch close, twitch close, 3=include twitch close, doublet open.
                resp = app.nnp.write(7, '1F57', 103, uint8(pmWrite),'uint8', 5); % writing 5 values
                app.writeErrorMsg(resp,'PM part 2');
            else
                app.TopMessage('Error reading PM part 2');
            end
        end
        
        function writeBpParameters(app)
            % write the BP parameters parameters to the BP
            % Need to write:
            %             2800.2	Sync interval
            %             3411.1	Gain
            %             3411.2	Depth (running average)
            %             3411.7	WFL Gain
            %             3511.1	Gain
            %             3511.2	Depth (running average)
            %             3511.7	WFL Gain
            
            bpnode=uint8(app.BPnode.Value);
            %the folloiwng are from read
            resp=app.nnp.write(bpnode,'2800', 2, uint8(app.SyncInt.Value),'uint8'); % sync interval
            app.writeErrorMsg(resp,'Sync Int');
            % block writes are not possible for remode modules so need to write one parameter at a time
            resp=app.nnp.write(bpnode,app.bpChanA,1,uint8(app.HWgain.Value) ,'uint8');
            app.writeErrorMsg(resp,'HWgain');
            resp=app.nnp.write(bpnode,app.bpChanA,2,uint8(app.Depth.Value) ,'uint8');
            app.writeErrorMsg(resp,'Depth');
            resp=app.nnp.write(bpnode,app.bpChanA,7,uint8(app.WFLgain.Value) ,'uint8');
            app.writeErrorMsg(resp,'WFLgain');
            resp=app.nnp.write(bpnode,app.bpChanB,1,uint8(app.HWgain_2.Value) ,'uint8');
            app.writeErrorMsg(resp,'HWgain2');
            resp=app.nnp.write(bpnode,app.bpChanB,2,uint8(app.Depth_2.Value) ,'uint8');
            app.writeErrorMsg(resp,'Depth2');
            resp=app.nnp.write(bpnode,app.bpChanB,7,uint8(app.WFLgain_2.Value) ,'uint8');
            app.writeErrorMsg(resp,'WFLgain2');
            app.TopMessage('Wrote BP Parameters');
        end
        
        function writeErrorMsg(app,resp,source)
            % send message if error writing using nnp
            % nnp.write returns 0 if successful
            if resp~=0
                ErrorMsg=['Error writing ',source];
                app.TopMessage(ErrorMsg);
            end
        end
        
    end
    
    methods (Access = private)
        % ------------------------------------------------------------------------------------------
        function restoreRadio(app)
            app.nnp.setRadioSettings(app.savedSettings);
            app.nnp.timeout = app.savedTimeout;
            if app.savedPMworInt > 0
                app.nnp.worOn(app.savedPMworInt); %reenable WOR on PM if it was enabled
            end
        end
        
        function saveRadio(app)
            %read back AP settings
            settings = app.nnp.getRadioSettings();
            if isempty(settings)
                msgbox('could not read back AccessPoint Settings','Radio Configuration', 'error');
                return;
            else
                app.savedSettings = settings;
            end
            app.savedTimeout = app.nnp.timeout;
            
            %is PM WOR enabled?
            resp = double(app.nnp.read(7, '2600', 9, 'uint16'));
            if length(resp)==1
                app.savedPMworInt = resp;
            else
                msgbox('could not determine if PM has WOR enabled, will get set to default 20ms');
                app.savedPMworInt = 20;
            end
        end
        
        
        function ReadPMBPValues(app)
            % read from the PM and BP node and fill the appropriate values
            % Turn network on
            if app.nnp.getNetworkStatus()==0
                app.setNetworkOn() % need network on to read teh BP module
            end
            pause(.2);
            % read PM to know whether to swap input values
            % 1F57.77 is to swap the input.  0x77=119
            resp = app.nnp.read(7, '1F57', 119, 'uint8'); %
            if length(resp)==1
                if resp>0 %swap inputs
                    app.bpChanA='3511';
                    app.bpChanB='3411';
                    app.chEditFieldA.Value=2;
                    app.chEditFieldB.Value=1;
                    app.TopMessage('BP Channels Swapped 2-1');
                    app.bpSwapAB=true;
                    app.BPEMGChannelConnectionsDropDown.Value='Reversed';
                    app.BPEMGChannelConnectionsDropDownValueChanged();
                else
                    app.bpChanA='3411';
                    app.bpChanB='3511';
                    app.chEditFieldA.Value=1;
                    app.chEditFieldB.Value=2;
                    app.TopMessage('BP Channels Normal 1-2');
                    app.bpSwapAB=false;
                    app.BPEMGChannelConnectionsDropDown.Value='Normal';
                    app.BPEMGChannelConnectionsDropDownValueChanged();
                end
                
            else
                 app.TopMessage('read swapMESInputs failed');
            end
            %
            % read from PM
            pmread=[]; %empty variable to store the data
            resp = app.nnp.read(7, '1F57', 1,16, 'uint8'); % need first 16 values
            if length(resp)==16 % all read
                % Note:  Need to add a separate buffer which I can
                % initialize and write to so that I can read both
                % channels at the same time and just switch the
                % display when changing channels without needing to
                % read again.
                % dec hex
                % 1	01	Prop Offset ASF
                % 2	02	Prop Gain*10 ASF
                % 3	03	Offset DASF
                % 4	04	Gain*10 DASF
                % 5	05	HighLimit MES1 D5
                % 6	06	Qthresh MES1 D2
                % 7	07	HighLimit MES2 D5
                % 8	08	Qthresh MES2D2
                % 9	09	HandPatMax
                % 10	0A	RotateThrough 1
                % 11	0B	Need Twitch to Lock
                % 12	0C	HoldLock CommandThresh
                % 13	0D	HoldLockTimeThresh
                % 14	0E	UnlockDelayThresh
                % 15	0F	Unlock Transition Steps
                % 16	10	Disable Locks
                
                pmread=double(resp); %values in edit boxes need double precision
                    
                    app.Poffset.Value=pmread(1);
                    app.Pgain.Value=pmread(2)/10;

                    % addded UpperTh and LowerTh
                    app.LowerTh.Value=app.Poffset.Value;
                    app.UpperTh.Value=(255/app.Pgain.Value)+app.Poffset.Value;

                    app.Poffset_2.Value=pmread(3);
                    app.Pgain_2.Value=pmread(4)/10;

                    app.D5high.Value=pmread(5)*10;
                    app.Quiet.Value=pmread(6);
                    
                    app.D5high_2.Value=pmread(7)*10;
                    app.Quiet_2.Value=pmread(8);
                    
                    app.Tw2Lock.Value=pmread(11);
                    app.LockCmdThresh.Value=pmread(12);
                    app.HoldCyclesLock.Value=pmread(13);
                    app.DisableLocks.Value=pmread(16);
                    app.DisableLocksValueChanged();
                    app.TopMessage('read parameters 1-16');
            else
                app.TopMessage('read parameters 1-16 failed');
            end

            resp = app.nnp.read(7, '1F57', 103,5, 'uint8'); % 5 values at 0x67-6B
                %                ind  dec   hex
                
                %                 17 103	67	Doublet enable subindex	u8	1F53, decimal 7 or 8
                %                 18 104	68	Single twitch subindex	u8	1F53, decimal 84 or 85 (0x40, 0x41)
                %                 19 105	69	Doublet logic subindex	u8	1F53, decimal 84 or 85 (0x40, 0x41)
                %                 20 106	6A	Long Twitch Change Patterns	u8	0 or 1, was 1F53.10
                %                 21 107	6B	Command Toggle Enable   u8	0=none, 1=button only, 2=include twitch close, twitch close, 3=include twitch close, doublet open.
                
                if length(resp)==5 % all read
                    pmread(17:21)=double(resp);
                    if pmread(17)==7
                        app.UnLockDoubletChannelDropDown.Value='EMG-A';
                   
                    elseif pmread(17)==8
                        app.UnLockDoubletChannelDropDown.Value='EMG-B';
                    else
                        app.UnLockDoubletChannelDropDown.Value='Unknown';
                    end
                    
                    if pmread(18)==84    % could use 18 or 19 because they change together
                        app.LockTwitchChannelDropDown.Value='EMG-A';
                    elseif pmread(18)==85
                        app.LockTwitchChannelDropDown.Value='EMG-B';
                    else
                        app.LockTwitchChannelDropDown.Value='Unknown';
                    end
%                     app.AllowPatternChange.Value=pmread(20);
                    app.PatternChangeDropDown.Value=pmread(20);
                    app.CommandToggleDropDown.Value=pmread(21);
                    app.CommandToggleDropDownValueChanged();
                    % Need to add command toggle enable.   Maybe not here, but read it for now just in case
                else
                    app.TopMessage('read parameters 17-21 failed');
                end

            % Sync Period
            resp = app.nnp.read(7, '1006', 0,'uint32'); % sync interval
            if length(resp)==1
                app.Sync.Value=double(resp);
            end
            
            % Read from BP module
            bpnode=uint8(app.BPnode.Value);
            resp=[];
            resp = app.nnp.read(bpnode, '2800', 2,'uint8'); % sync interval
            if length(resp)==1
                app.SyncInt.Value=double(resp);
                app.TopMessage('Sync Int');
            else
                app.TopMessage('Error Sync Int');
            end
            pause(.1);
            resp=[];
            resp = app.nnp.read(bpnode,app.bpChanA, 1, 2,'uint8'); % channel parameters  Cannot read 7 parameters at a time
            
            if length(resp)==2
                chpar=double(resp);
                app.HWgain.Value=chpar(1);
                app.Depth.Value=chpar(2);
                %                         app.WFLgain.Value=chpar(7);
                app.TopMessage('BP ch1 par');
            else
                app.TopMessage('Error BP ch1 par');
            end
            pause(.1);
            resp=[];
            resp = app.nnp.read(bpnode,app.bpChanA, 7,'uint8'); % channel parameters  Cannot read 7 parameters at a time
            if length(resp)==1
                app.WFLgain.Value=double(resp);
                app.TopMessage('WFLgain 1');
            else
                app.TopMessage('Error WFLgain 1');
            end
            pause(.1);
            resp=[];
            resp = app.nnp.read(bpnode, app.bpChanB, 1, 2,'uint8'); % channel parameters
            if length(resp)==2
                chpar=double(resp);
                app.HWgain_2.Value=chpar(1);
                app.Depth_2.Value=chpar(2);
                app.TopMessage('BP ch2 par');
            else
                app.TopMessage('Error BP ch2 par');
            end
            
            pause(.1);
            resp=[];
            resp = app.nnp.read(bpnode,app.bpChanB, 7,'uint8'); % channel parameters  Cannot read 7 parameters at a time
            if length(resp)==1
                app.WFLgain_2.Value=double(resp);
                app.TopMessage('WFLgain 2');
            else
                app.TopMessage('Error WFLgain 2');
            end
            pause(.1);
            
            app.refreshWFLylines();
            app.refreshD5ylines();
        end
        
        function TopMessage(app,message)
            app.Messages.Value=vertcat(message, string(app.Messages.Value)); % put new message on top
        end
        
        
        function ClearMessage(app)
            app.Messages.Value=''; % clear messages
        end
        
        function changeDefaultInteractions(app)
            % the default interactions stink, so change the defaults, then
            % you can enable and disable them.
            app.EMGAxes.Interactions =[zoomInteraction panInteraction];
            app.ScaledAxes.Interactions =[zoomInteraction panInteraction ];
            app.D5Axes.Interactions =[zoomInteraction panInteraction];
            app.TwitchAxes.Interactions =[zoomInteraction panInteraction ];
            app.HLAxes.Interactions =[zoomInteraction panInteraction ];
            app.ASFAxes.Interactions =[zoomInteraction panInteraction ];
            app.EMGAxes_2.Interactions =[zoomInteraction panInteraction ];
            app.ScaledAxes_2.Interactions =[zoomInteraction panInteraction ];
            app.D5Axes_2.Interactions =[zoomInteraction panInteraction ];
            app.TwitchAxes_2.Interactions =[zoomInteraction panInteraction ];
            app.HLAxes_2.Interactions =[zoomInteraction panInteraction ];
            app.ASFAxes_2.Interactions =[zoomInteraction panInteraction ];
            app.ComAxes_2.Interactions =[zoomInteraction panInteraction ]; %app.ComAxes_2
            
            app.EMGAxes.Interactions(1).Dimensions='y'; %set the zoom to y only
            app.ScaledAxes.Interactions(1).Dimensions='y'; %set the zoom to y only
            app.D5Axes.Interactions(1).Dimensions='y'; %set the zoom to y only
            app.TwitchAxes.Interactions(1).Dimensions='y'; %set the zoom to y only
            app.HLAxes.Interactions(1).Dimensions='y'; %set the zoom to y only
            app.ASFAxes_2.Interactions(1).Dimensions='y'; %set the zoom to y only
            app.EMGAxes_2.Interactions(1).Dimensions='y'; %set the zoom to y only
            app.ScaledAxes_2.Interactions(1).Dimensions='y'; %set the zoom to y only
            app.D5Axes_2.Interactions(1).Dimensions='y'; %set the zoom to y only
            app.TwitchAxes_2.Interactions(1).Dimensions='y'; %set the zoom to y only
            app.HLAxes_2.Interactions(1).Dimensions='y'; %set the zoom to y only
            app.ComAxes_2.Interactions(1).Dimensions='y'; %set the zoom to y only
            
            app.EMGAxes.Interactions(2).Dimensions='y'; %set the zoom to y only
            app.ScaledAxes.Interactions(2).Dimensions='y'; %set the zoom to y only
            app.D5Axes.Interactions(2).Dimensions='y'; %set the zoom to y only
            app.TwitchAxes.Interactions(2).Dimensions='y'; %set the zoom to y only
            app.HLAxes.Interactions(2).Dimensions='y'; %set the zoom to y only
            app.ASFAxes_2.Interactions(2).Dimensions='y'; %set the zoom to y only
            app.EMGAxes_2.Interactions(2).Dimensions='y'; %set the zoom to y only
            app.ScaledAxes_2.Interactions(2).Dimensions='y'; %set the zoom to y only
            app.D5Axes_2.Interactions(2).Dimensions='y'; %set the zoom to y only
            app.TwitchAxes_2.Interactions(2).Dimensions='y'; %set the zoom to y only
            app.HLAxes_2.Interactions(2).Dimensions='y'; %set the zoom to y only
            app.ComAxes_2.Interactions(2).Dimensions='y'; %set the zoom to y only
        end
        
        function disableGraphInteractions(app)
            disableDefaultInteractivity(app.EMGAxes); %to prevent clicking points with cursor
            disableDefaultInteractivity(app.ScaledAxes); %to prevent clicking points with cursor
            disableDefaultInteractivity(app.D5Axes); %to prevent clicking points with cursor
            disableDefaultInteractivity(app.TwitchAxes); %to prevent clicking points with cursor
            disableDefaultInteractivity(app.HLAxes); %to prevent clicking points with cursor
            disableDefaultInteractivity(app.ASFAxes); %to prevent clicking points with cursor
            
            disableDefaultInteractivity(app.EMGAxes_2); %to prevent clicking points with cursor
            disableDefaultInteractivity(app.ScaledAxes_2); %to prevent clicking points with cursor
            disableDefaultInteractivity(app.D5Axes_2); %to prevent clicking points with cursor
            disableDefaultInteractivity(app.TwitchAxes_2); %to prevent clicking points with cursor
            disableDefaultInteractivity(app.HLAxes_2); %to prevent clicking points with cursor
            disableDefaultInteractivity(app.ASFAxes_2); %to prevent clicking points with cursor
            disableDefaultInteractivity(app.ComAxes_2); %to prevent clicking points with cursor
        end
        
        function enableGraphInteractions(app)
            enableDefaultInteractivity(app.EMGAxes); %
            enableDefaultInteractivity(app.ScaledAxes); %
            enableDefaultInteractivity(app.D5Axes); %
            enableDefaultInteractivity(app.TwitchAxes); %
            enableDefaultInteractivity(app.HLAxes); %
            enableDefaultInteractivity(app.ASFAxes); %
            
            enableDefaultInteractivity(app.EMGAxes_2); %
            enableDefaultInteractivity(app.ScaledAxes_2); %
            enableDefaultInteractivity(app.D5Axes_2); %
            enableDefaultInteractivity(app.TwitchAxes_2); %
            enableDefaultInteractivity(app.HLAxes_2); %
            enableDefaultInteractivity(app.ASFAxes_2); %
            enableDefaultInteractivity(app.ComAxes_2); %
        end


        function showhideAxesToolbars(app,sh)
            % make this a show/hide function by adding string input on or off
            app.EMGAxes.Toolbar.Visible = sh;  %to make the little toolbar at top right disappear.
            app.ScaledAxes.Toolbar.Visible = sh;  %to make the little toolbar at top right disappear.
            app.D5Axes.Toolbar.Visible = sh;  %to make the little toolbar at top right disappear.
            app.TwitchAxes.Toolbar.Visible = sh;  %to make the little toolbar at top right disappear
            app.HLAxes.Toolbar.Visible = sh;  %to make the little toolbar at top right disappear.
            app.ASFAxes.Toolbar.Visible = sh;  %to make the little toolbar at top right disappear.
            app.EMGAxes_2.Toolbar.Visible = sh;  %to make the little toolbar at top right disappear.
            
            app.ScaledAxes_2.Toolbar.Visible = sh;  %to make the little toolbar at top right disappear.
            app.D5Axes_2.Toolbar.Visible = sh;  %to make the little toolbar at top right disappear.
            app.TwitchAxes_2.Toolbar.Visible = sh;  %to make the little toolbar at top right disappear.
            app.HLAxes_2.Toolbar.Visible = sh;  %to make the little toolbar at top right disappear.
            app.ASFAxes_2.Toolbar.Visible = sh;  %to make the little toolbar at top right disappear.
            app.ComAxes_2.Toolbar.Visible = sh;  %to make the little toolbar at top right disappear.
        end
        
        function setDefaultXlimits(app)
            app.EMGAxes.XLim = app.defaultXlimits;
            app.ScaledAxes.XLim = app.defaultXlimits;
            app.D5Axes.XLim = app.defaultXlimits;
            app.TwitchAxes.XLim = app.defaultXlimits;
            app.HLAxes.XLim = app.defaultXlimits;
            app.ASFAxes.XLim = app.defaultXlimits;
            app.EMGAxes_2.XLim = app.defaultXlimits;
            app.ScaledAxes_2.XLim = app.defaultXlimits;
            app.D5Axes_2.XLim = app.defaultXlimits;
            app.TwitchAxes_2.XLim = app.defaultXlimits;
            app.HLAxes_2.XLim = app.defaultXlimits;
            app.ASFAxes_2.XLim = app.defaultXlimits;
            app.ComAxes_2.XLim = app.defaultXlimits;
        end
        
        function setDefaultYlimits(app)
            % use this to reset the defaults
            % need some sort of configuration table so that these could be
            % changed to new defaults if you wanted to.  Maybe a separate
            % window you could call?
            app.EMGAxes.YLim = [0 255];
            app.ScaledAxes.YLim = [0 255];
            app.D5Axes.YLim = [-1000 1000];
            app.TwitchAxes.YLim = [0 5];
            app.HLAxes.YLim = [0 25];
            app.ASFAxes.YLim = [0 255];
            app.EMGAxes_2.YLim = [0 255];
            app.ScaledAxes_2.YLim = [0 255];
            app.D5Axes_2.YLim = [-1000 1000];
            app.TwitchAxes_2.YLim = [0 5];
            app.HLAxes_2.YLim = [0 25];
            app.ASFAxes_2.YLim = [0 255];
            app.ComAxes_2.YLim = [0 255];
        end
        
        
        function results = makeHeader(app)
            % replace below with the new header I creaete
            %    results='Date,PMPowerAv,PMPowerInst,Vsys,Vnet,NetOn,PMCANErr,APRSSI,PMtemp,PMmode,PMErr';
            %             results=['01-Counter,02-WFL1,03-Scaled1,'...
            %                 '04-ASF1,05-WFL2,06-Scaled2,07-ASF2,08-Diff5M1,'...
            %                 '09-HLCount1,10-MES1SLD,11-Diff5M2,12-HLCount2,'...
            %                 '13-MES2SLD,14-ComHand,15-LockStatus,16-PatHand'];
            results=['Counter, WFL1,Scaled1,'...
                'ASF1,WFL2,Scaled2,ASF2,Diff5M1,'...
                'HLCnt1,MES1SLD,Diff5M2,HLCnt2,'...
                'MES2SLD,ComHand,LockStatus,PatHand'];
            results=sprintf([results,'\n']);  %add carriage return
        end
        
        function GetNewFileNameFromDialog(app)
            % get the current file names
            %user=app.UserEditField.Value;
            user=app.defaultName;
            %folder=app.FolderEditField.Value;
            folder=app.defaultPath;
            prefix='_PrEmg_';
            % call the dialog box with input values
            app.FileNameDialog=fileNamesDialogBox(app,user,folder,prefix);
        end
        
        function updateEMGParArray(app)
            % Update the array to be saved to file
            % reverse the order of filling the values.
            % NOTE:  Will need to add the Dual ASF gain and offset at some point
            % emgParameters array
            % 1-16 = N7:1F57.(1-16)dec
            % 17-21 =N7:1F57.(103-107)  -  inserted parameter here
            % 22 = N7:1006.0  sync period
            % 23 = N9:2800 sync interval
            % 24-25 = N9:3411.(1-2) HWgain, depth
            % 26 =    N9:3411.(7) WFLgain
            % 27-28 = N9:3511.(1-2) HWgain, depth
            % 29 =    N9:3511.(7) WFLgain
 
            % add UpperTh and LowerTh, so use those to update Pgain
            app.Pgain.Value=255/(app.UpperTh.Value-app.LowerTh.Value);
            app.UpperTh.Value=(255/app.Pgain.Value)+app.Poffset.Value;

            app.emgParameters(1)=app.Poffset.Value;
            app.emgParameters(2)=app.Pgain.Value;
            % Note, 3 and 4 are used with standing transfer, dual-ASF
            app.emgParameters(3)=app.Poffset_2.Value;
            app.emgParameters(4)=app.Pgain_2.Value;
            app.emgParameters(5)=app.D5high.Value/10;
            app.emgParameters(6)=app.Quiet.Value;
            
            app.emgParameters(7)=app.D5high_2.Value/10;
            app.emgParameters(8)=app.Quiet_2.Value;
            
            app.emgParameters(11)=app.Tw2Lock.Value;
            app.emgParameters(12)=app.LockCmdThresh.Value;
            app.emgParameters(13)=app.HoldCyclesLock.Value;
            app.emgParameters(16)=app.DisableLocks.Value;
            
            if strcmp(app.UnLockDoubletChannelDropDown.Value,'EMG-A')
                app.emgParameters(17)=7;
            end
            if strcmp(app.UnLockDoubletChannelDropDown.Value,'EMG-B')
                app.emgParameters(17)=8;
                
            end
            if strcmp(app.LockTwitchChannelDropDown.Value,'EMG-A')
                app.emgParameters(18)=84;
            end
            if strcmp(app.LockTwitchChannelDropDown.Value,'EMG-B')
                app.emgParameters(18)=85;
            end
%             app.emgParameters(20)=app.AllowPatternChange.Value;
            app.emgParameters(20)=app.PatternChangeDropDown.Value;
            app.emgParameters(21)=app.CommandToggleDropDown.Value;
            
            % Sync Period
            app.emgParameters(22)=app.Sync.Value;
            
            % BP module
            app.emgParameters(23)=app.SyncInt.Value;
            app.emgParameters(24)=app.HWgain.Value;
            app.emgParameters(25)=app.Depth.Value;
            app.emgParameters(26)=app.WFLgain.Value;
            app.emgParameters(27)=app.HWgain_2.Value;
            app.emgParameters(28)=app.Depth_2.Value;
            app.emgParameters(29)=app.WFLgain_2.Value;
        end
        
        function updateEMGParameters(app)
            % this should be mostly like the OD read, except the values are stored in an array, app.emgParameters
            % emgParameters array
            % 1-16 = N7:1F57.(1-16)dec
            % 17-21 =N7:1F57.(103-107)  -  inserted parameter here
            % 22 = N7:1006.0  sync period
            % 23 = N9:2800 sync interval
            % 24-25 = N9:3411.(1-2) HWgain, depth
            % 26 =    N9:3411.(7) WFLgain
            % 27-28 = N9:3511.(1-2) HWgain, depth
            % 29 =    N9:3511.(7) WFLgain
            
            app.Poffset.Value=app.emgParameters(1);
            app.Pgain.Value=app.emgParameters(2);
            % Added LowerTh and UpperTh, so calculate
            app.LowerTh.Value=app.Poffset.Value;
            app.UpperTh.Value=(255/app.Pgain.Value)+app.Poffset.Value;

            % Note, 3 and 4 are used with standing transfer
            app.D5high.Value=app.emgParameters(5)*10;
            app.Quiet.Value=app.emgParameters(6);
            
            app.D5high_2.Value=app.emgParameters(7)*10;
            app.Quiet_2.Value=app.emgParameters(8);
            
            app.Tw2Lock.Value=app.emgParameters(11);
            app.LockCmdThresh.Value=app.emgParameters(12);
            app.HoldCyclesLock.Value=app.emgParameters(13);
            app.DisableLocks.Value=app.emgParameters(16);
            app.DisableLocksValueChanged();
            
            if app.emgParameters(17)==7
                app.UnLockDoubletChannelDropDown.Value='EMG-A';
            elseif app.emgParameters(17)==8
                app.UnLockDoubletChannelDropDown.Value='EMG-B';
            else
               app.UnLockDoubletChannelDropDown.Value='Unknown'; 
            end

            if app.emgParameters(18)==84
                app.LockTwitchChannelDropDown.Value='EMG-A';
            
            elseif app.emgParameters(18)==85
                app.LockTwitchChannelDropDown.Value='EMG-B';
            else
                app.LockTwitchChannelDropDown.Value='Unknown';
            end
            %            app.AllowPatternChange.Value=app.emgParameters(20);
            app.PatternChangeDropDown.Value=app.emgParameters(20);
            app.CommandToggleDropDown.Value=app.emgParameters(21);
            app.CommandToggleDropDownValueChanged();
            
            % Sync Period
            app.Sync.Value=app.emgParameters(22);
            
            % BP module
            app.SyncInt.Value=app.emgParameters(23);
            app.HWgain.Value=app.emgParameters(24);
            app.Depth.Value=app.emgParameters(25);
            app.WFLgain.Value=app.emgParameters(26);
            app.HWgain_2.Value=app.emgParameters(27);
            app.Depth_2.Value=app.emgParameters(28);
            app.WFLgain_2.Value=app.emgParameters(29);
            
            app.refreshWFLylines();
            app.refreshD5ylines();
        end
        
        function val = LockTable2Dec(~,LDtable)
            val=bin2dec(num2str(flip(LDtable{1:6,1}'))); % use only six bits
        end
        
        function val = Dec2LockTable(~,numDec)
            val=table(); % This gives me the bits.  Now need to create a table
            val{[1:6],1}=logical(bitget(uint8(numDec),[1:6]))';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin<2
                NNP=[];
            else
                NNP=varargin{1};
            end
            useNNP=true;
            if isempty(NNP)
                app.nnp = NNPAPI();
                if isempty(app.nnp.port)
                    %msgbox('No port chosen for NNP Access Point')
                    uialert(app.UIFigure,'No port chosen for NNP Access Point','No NNP AP')
                    useNNP=false;
                    delete(app.nnp)
%                    return
                end
            else
                app.nnp = NNP;
            end
            if nargin>2
                app.callingFigure=varargin{2};
            end

            if nargin>3  % third is userFolders
                app.userFolders=varargin{3};  % userfiles, code, user, config, data, log
                if isfield(app.userFolders,'code')&& ~isempty(app.userFolders.code)
                    app.UserIDEditField.Value=app.userFolders.code;
                end
                if isfield(app.userFolders,'data')&& ~isempty(app.userFolders.data)
                    app.defaultName=[app.userFolders.code,app.defaultSuffix]; % name without extension
                    app.defaultPath=app.userFolders.data;
                end   
             end
        if useNNP

            % parameter you need to set manually
            app.CommandToggleDropDown.ItemsData=[0 1 2 3]; % 0=none, 1=button only, 2=include twitch close, twitch close, 3=include twitch close, doublet open.
            app.PatternChangeDropDown.ItemsData=[0 1]; % 0=normal, 1=Buttons or Long Twitch
            
            % default file names
            % NewFileNames(app,user,folder,logname,commentname)
            app.selpath=pwd;% get the current working directory
            app.NewFileNames(app.UserIDEditField.Value,app.selpath,fullfile(app.selpath,'LogFile1.csv'),fullfile(app.selpath,'CommentFile1.csv'));
            % app.ChannelDropDown.Value='2'; %start with the second channel selected
            % set default tabs
            app.TabGroup_2.SelectedTab=app.ProportionalTab_2;   % MES2 defaults to proportional
            app.TabGroup.SelectedTab=app.LogicalTab;  % MES1 defaults to logical control
            
            %First set of plots
            app.emgbuf = nan(app.emgsamp, 1);
            app.scaledbuf = nan(app.emgsamp, 1);
            app.d5buf = nan(app.emgsamp, 1);
            app.twitchbuf = nan(app.emgsamp, 1);
            app.hlbuf = nan(app.emgsamp, 1);
            app.asfbuf = nan(app.emgsamp, 1);
            
            
            app.pWFL=plot(app.EMGAxes, 1:app.emgsamp, app.emgbuf);

            % added LowerTh and UpperTh
            app.Poffset.Value=app.LowerTh.Value;
            app.Pgain.Value=255/(app.UpperTh.Value-app.LowerTh.Value);

            app.emgParameters(2)=app.Pgain.Value; % do I need this here?
            app.emgParameters(4)=app.Pgain_2.Value; 
            if app.useTrunkCommand %leave this offset and upper her for first pass.
                offset = app.Poffset_2.Value;
                upper = (255/app.Pgain_2.Value)+offset;
            else
%                 offset = app.Poffset.Value;
%                 upper = (255/app.Pgain.Value)+offset;
                offset = app.LowerTh.Value;
                upper = app.UpperTh.Value;
            end 

            app.pWFLoff=yline(app.EMGAxes,offset,'g');
            app.pWFLgain=yline(app.EMGAxes,upper,'r');

            plot(app.ScaledAxes, 1:app.emgsamp, app.scaledbuf);
            app.pD51=plot(app.D5Axes, 1:app.emgsamp, app.d5buf);
            high=app.D5high.Value;
            low=app.D5high.Value*-1;
            app.pD5high1=yline(app.D5Axes,high,'r');
            app.pD5low1=yline(app.D5Axes,low,'g');
            app.pTw=plot(app.TwitchAxes, 1:app.emgsamp, app.twitchbuf);
            plot(app.HLAxes, 1:app.emgsamp, app.hlbuf);
            plot(app.ASFAxes, 1:app.emgsamp, app.asfbuf);
            
            % end first set of plots
            
            %Second set of plots
            app.emgbuf2 = nan(app.emgsamp, 1);
            app.scaledbuf2 = nan(app.emgsamp, 1);
            app.d5buf2 = nan(app.emgsamp, 1);
            app.twitchbuf2 = nan(app.emgsamp, 1);
            app.hlbuf2 = nan(app.emgsamp, 1);
            app.asfbuf2 = nan(app.emgsamp, 1);
            app.combuf2 = nan(app.emgsamp, 1);
            app.pWFL2=plot(app.EMGAxes_2, 1:app.emgsamp, app.emgbuf);
            if app.useTrunkCommand
                offset = app.Poffset_2.Value;
                upper = (255/app.Pgain_2.Value)+offset;
            else
%                 offset = app.Poffset.Value;
%                 upper = (255/app.Pgain.Value)+offset;
                offset = app.LowerTh.Value;
                upper = app.UpperTh.Value;
            end 

            app.pWFLoff2=yline(app.EMGAxes_2,offset,'g');
            app.pWFLgain2=yline(app.EMGAxes_2,upper,'r');
            plot(app.ScaledAxes_2, 1:app.emgsamp, app.scaledbuf2);
            app.pD52=plot(app.D5Axes_2, 1:app.emgsamp, app.d5buf2);
            high=app.D5high_2.Value;
            low=app.D5high_2.Value*-1;
            app.pD5high2=yline(app.D5Axes_2,high,'r');
            app.pD5low2=yline(app.D5Axes_2,low,'g');
            plot(app.TwitchAxes_2, 1:app.emgsamp, app.twitchbuf2);
            plot(app.HLAxes_2, 1:app.emgsamp, app.hlbuf2);
            plot(app.ASFAxes_2, 1:app.emgsamp, app.asfbuf2);
            plot(app.ComAxes_2, 1:app.emgsamp, app.combuf2);
            % end of second set of plots
            
            app.setDefaultXlimits();
            app.setDefaultYlimits(); %need function to reset plots to default Ylim since Home
            app.changeDefaultInteractions();
            app.showhideAxesToolbars('off'); % since the button defaults to off
            
            %doesn't do that correctly
            pause(0.1);
            
            app.emgTimer = timer();
            app.emgTimer.Period = 0.1;
            app.emgTimer.ExecutionMode = 'fixedRate';
            app.emgTimer.BusyMode = 'drop';
%             app.emgTimer.BusyMode = 'queue';
            app.emgTimer.TimerFcn = @app.updateEMG;
            
            %            app.ReadPMBPValues(); % Don't want this at startup until we chekc whether communication is present
            
            app.LockDisable=table([false;false;false;false;false;false]); % bit 0 is disable all, bits 1-5 for patterns 1-5
            app.LockDisableTable.Data=app.LockDisable;
            % convert bits to integer
            LD=app.LockTable2Dec(app.LockDisable);
            app.DisableLocks.Value=LD; % this is now double
            app.templockdisable=app.DisableLocks.Value; %use the default to start with in case the enable button is pressed.
        end
         app.FolderEditField.Value=app.defaultPath;  % start with current path
        end

        % Value changed function: ViewEMGButton
        function ViewEMGButtonValueChanged(app, event)
            if app.ViewEMGButton.Value
                app.startEMG();
                app.ViewEMGButton.Enable = 'on';
                app.netLED.Enable = 'on';
                app.NetLabel.Enable = 'on';
                app.ptLED.Enable = 'on';
                app.PtLabel.Enable = 'on';
                app.GainSetADropDown.Enable = 'off';
                app.GainSetBDropDown.Enable = 'off';
            else
                app.stopEMG();
                app.netLED.Enable = 'off';
                app.NetLabel.Enable = 'off';
                app.ptLED.Enable = 'off';
                app.PtLabel.Enable = 'off';
                app.GainSetADropDown.Enable = 'on';
                app.GainSetBDropDown.Enable = 'on';
            end
        end

        % Button pushed function: ActionGo
        function ActionGoButtonPushed(app, event)
            %  app.Messages.Value=''; %clear the message first
            switch str2double(app.Script.Value) %which to perform
                case 1 % Startup
                    app.runScript(7,'Startup Script');
                    
                case 2 % MES Logic
                    if ~app.nnp.networkOn() % will put into waiting, returns true if successful
                        app.TopMessage('Network On failed');
                        return;
                    else
                        app.TopMessage('Network On');
                        
                        pause(.5); %need to wait for network to start
                        if  ~app.nnp.enterPatient() % patient mode, returns true if successful
                            app.TopMessage('Patient mode failed');
                            return;
                        else
                            app.TopMessage('Patient mode');
                        end
                        app.runScript(2,'MES1 Logic script');
                        app.runScript(15,'MES2 Logic script');
                    end
                    
                case 3 % Hand ADL 1
                    if app.nnp.write(7,'1F53',86,uint8(0),'uint8') % Enable stim pattern set
                        % this is to re-enable the stim patterns after having disabled them previously
                        app.TopMessage('Enable Stim Pattern Set failed');
                    else
                        app.TopMessage('Enable Stim Pattern Set');
                    end
                    app.startADL(1);
                    
                case 4 % Hand ADL 2
                    if app.nnp.write(7,'1F53',86,uint8(0),'uint8') % Enable stim pattern set
                        % this is to re-enable the stim patterns after having disabled them previously
                        app.TopMessage('Enable Stim Pattern Set failed');
                    else
                        app.TopMessage('Enable Stim Pattern Set');
                    end
                    app.startADL(2);
                    
                case 5 % Network on in patient mode, but no stim
                    % I want to run ADL but make stim pattern = 0.
                    % first reset by stopping the network
                    if ~app.stopADL()
                        app.TopMessage('stop ADL failed');
                        return;
                    else
                        app.TopMessage('stop ADL');
                    end
                    % Then start the Hand Control script
                    pause(1);
                    
                    app.startADL(1);
                    if app.nnp.write(7,'1F53',86,uint8(1),'uint8') % disable Stim pattern set.  FGupdate does not allow FG>0 if set to 1
                        app.TopMessage('Disable Stim Pattern Set failed');
                    else
                        app.TopMessage('Disable Stim Pattern Set');
                    end
                    
                case 6 % Stop ADL
                    if ~app.stopADL()
                        app.TopMessage('stop ADL failed');
                        return;
                    else
                        app.TopMessage('stop ADL');
                    end
                    if app.nnp.write(7,'1F53',86,uint8(0),'uint8') % Enable stim pattern set
                        % this is to re-enable the stim patterns after having disabled them previously
                        app.TopMessage('Enable Stim Pattern Set failed');
                    else
                        app.TopMessage('Enable Stim Pattern Set');
                    end
                    
%                 case 7 % Dual ASF
%                     if app.nnp.write(7,'1F53',98,uint8(1),'uint8') % reset Dual ASF
%                         app.TopMessage('reset dual ASF failed');
%                         return;
%                     else
%                         app.TopMessage('reset dual ASF');
%                     end
%                     app.runScript(12,'start Dual ASF script');
%                 case 8 % Dual ASF Reset
%                     if app.nnp.write(7,'1F53',98,uint8(1),'uint8') % reset Dual ASF
%                         app.TopMessage('reset dual ASF failed');
%                     else
%                         app.TopMessage('Reset dual ASF');
%                     end
%                 case 9 % Dual ASF Disable Oneshot
%                     if app.nnp.write(7,'1F57',137,uint8(0),'uint8') % Disable Dual ASF Oneshot
%                         app.TopMessage('Disable dual ASF Oneshot failed');
%                     else
%                         app.TopMessage('Disable dual ASF oneshot');
%                     end
%                 case 10 % Dual ASF Enable Oneshot
%                     if app.nnp.write(7,'1F57',137,uint8(1),'uint8') % Enable Dual ASF Oneshot
%                         app.TopMessage('Enable dual ASF Oneshot failed');
%                     else
%                         app.TopMessage('Enable dual ASF oneshot');
%                     end
                otherwise
                    app.TopMessage('No action chosen');
            end
        end

        % Button pushed function: DisableLocksButton
        function DisableLocksButtonPushed(app, event)
            % copy existing lock parameters to temporary
            resp=app.nnp.read(7,'1F57',16,'uint8');
            if length(resp)==1
                app.templockdisable=double(resp);
                if app.nnp.write(7,'1F57',16,uint8(1),'uint8') % 01 means disable all
                    app.TopMessage('Lock disable not updated');
                else
                    app.DisableLocks.Value=1; %update if not error in writing
                end
            else
                app.TopMessage('Lock disable not read');
            end
        end

        % Button pushed function: RestoreLocksButton
        function RestoreLocksButtonPushed(app, event)
            oldlock=uint8(app.templockdisable);
            if app.nnp.write(7,'1F57',16,oldlock,'uint8') % write old lock values to OD
                app.TopMessage('Lock disable values not updated');
            else
                app.DisableLocks.Value=app.templockdisable; %update if not error in writing
            end
        end

        % Button pushed function: EnableDoublet
        function EnableDoubletButtonPushed(app, event)
            % Enable doublets for the channel selected
            si=uint8(7); % 7 or 8
            if app.nnp.write(7,'1F53',si,uint8(1),'uint8') % write old lock values to OD
                %                 app.TopMessage('Doublet enable not updated');
                app.TopMessage('Doublet 1 NOT ENabled');
            else
                app.TopMessage('Doublet 1 ENabled');
            end
        end

        % Button pushed function: ODGOButton
        function ODGOButtonPushed(app, event)
            switch app.ModulesDropDown.Value %which to perform
                case '1' % Read MES parameters from BP and PM
                    app.ReadPMBPValues();
                case '2' % Status of Remote Modules
                    rmParametersStatus(app.nnp);

                case '3' % Write EMG parameters to PM and BP modules
                    app.writeBpParameters();
                    app.writePmParameters();
                case '4' % save PM OD
                    resp=app.nnp.nmt(7,'0A');
                    if (resp == hex2dec('0A'))
                        message='PM OD Saved';
                    else
                        message=['PM OD Save failed'];
                    end
                    app.TopMessage(message);
                case '5' % save BP OD
                    % BP must be in waiting mode to save OD
                    if ~app.nnp.enterWaiting()
                        message='Could not enter waiting';
                    else
                        resp=app.nnp.nmt(app.BPnode,'0A');
                        if (resp == hex2dec('0A'))
                            message='BP OD Saved';
                        else
                            message=['BP OD Save failed'];
                        end
                    end
                    app.TopMessage(message);

            end
        end

        % Button pushed function: DisableDouble
        function DisableDoubleButtonPushed(app, event)
            % Disable doublets for the channel selected
            si=uint8(7); % 7 or 8
            if app.nnp.write(7,'1F53',si,uint8(0),'uint8') % write old lock values to OD
                %                 app.TopMessage('Doublet disable not updated');
                app.TopMessage('Doublet 1 NOT DISabled');
            else
                app.TopMessage('Doublet 1 DISabled');
            end
        end

        % Value changed function: Poffset
        function PoffsetValueChanged(app, event)
            app.refreshWFLylines();
        end

        % Value changed function: Pgain
        function PgainValueChanged(app, event)
            app.refreshWFLylines();
            
        end

        % Button pushed function: EnableDoublet_2
        function EnableDoublet_2ButtonPushed(app, event)
            si=uint8(8); % 7 or 8
            if app.nnp.write(7,'1F53',si,uint8(1),'uint8') % write old lock values to OD
                %                 app.TopMessage('Doublet enable not updated');
                app.TopMessage('Doublet 2 NOT ENabled');
            else
                app.TopMessage('Doublet 2 ENabled');
            end
        end

        % Button pushed function: DisableDouble_2
        function DisableDouble_2ButtonPushed(app, event)
            si=uint8(8); % 7 or 8
            if app.nnp.write(7,'1F53',si,uint8(0),'uint8') % write old lock values to OD
                %app.TopMessage('Doublet disable not updated');
                app.TopMessage('Doublet 2 NOT DISabled');
            else
                app.TopMessage('Doublet 2 DISabled');
            end
        end

        % Button pushed function: ClearMessageButton
        function ClearMessageButtonPushed(app, event)
            app.ClearMessage();
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            % update parameters related to WFL1, BP 3411.n
            % Can only change gain in waiting mode.
            if app.ViewEMGButton.Value % need to stop recording first
                app.ViewEMGButton.Value=false; %stop recording
                pause(1);
                app.ViewEMGButtonValueChanged([]);
                %will the buttonchange function run or do I need to
                %manually change?
                pause(1);
            end
            % Get mode state from 1F53.26
            currentMode=uint8(0);
            resp = app.nnp.read(7, '1F53', 38,'uint8'); % nodeState
            if length(resp)==1
                currentMode=resp;
            else
                app.TopMessage('current Mode not read');
            end
            
            % if n=mode is not in waiting
            if currentMode~=1
                if ~app.nnp.enterWaiting() % put into waiting mode
                    app.TopMessage('could not enter Waiting');
                end
            end

            if ~app.nnp.write(app.BPmod,app.bpChanA,1,uint8(app.HWgain.Value),'uint8') %1
                app.TopMessage('HWgain 1');
            else
                app.TopMessage('Error HWgain 1');
            end
            if ~app.nnp.write(app.BPmod,app.bpChanA,2,uint8(app.Depth.Value),'uint8')  %2
                app.TopMessage('Depth 1');
            end
            if ~app.nnp.write(app.BPmod,app.bpChanA,7,uint8(app.WFLgain.Value),'uint8') %7
                app.TopMessage('WFLgain 1');
                app.refreshWFLylines();
            end
           % if currentMode is patient
            if currentMode==5
                if ~app.nnp.enterPatient()
                    app.TopMessage('could not enter Patient mode');
                else
                    app.TopMessage('back in Patient Mode');
                end
            end
            
           
           % otherwise leave in waiting
        end

        % Button pushed function: UpdateButton_2
        function UpdateButton_2Pushed(app, event)
            % update parameters related to WFL1, BP 3511.n
            if app.ViewEMGButton.Value % need to stop recording first
                app.ViewEMGButton.Value=false; %stop recording
                pause(1);
                app.ViewEMGButtonValueChanged([]);
                %will the buttonchange function run or do I need to
                %manually change?
                pause(1);
            end
            currentMode=uint8(0);
            resp = app.nnp.read(7, '1F53', 38,'uint8'); % nodeState
            if length(resp)==1
                currentMode=resp;
            else
                app.TopMessage('current Mode not read');
            end
            
            % if n=mode is not in waiting
            if currentMode~=1
                if ~app.nnp.enterWaiting() % put into waiting mode
                    app.TopMessage('could not enter Waiting');
                end
            end
            % NOTE:  Could use resp=app.nnp.setBPGains(node, ch1, ch2),
            % resp = NNP.nmt(node, 'D0', ch1, ch2);
            % success = (resp == hex2dec('D0'));
            
            if ~app.nnp.write(app.BPmod,app.bpChanB,1,uint8(app.HWgain_2.Value),'uint8') %1
                app.TopMessage('HWgain 2');
            end
            if ~app.nnp.write(app.BPmod,app.bpChanB,2,uint8(app.Depth_2.Value),'uint8')  %2
                app.TopMessage('Depth 2');
            end
            if ~app.nnp.write(app.BPmod,app.bpChanB,7,uint8(app.WFLgain_2.Value),'uint8') %7
                app.TopMessage('WFLgain 2');
                app.refreshWFLylines();
            end
            % 
             % if currentMode is patient
            if currentMode==5
                if ~app.nnp.enterPatient()
                    app.TopMessage('could not enter Patient mode');
                else
                    app.TopMessage('back in Patient Mode');
                end
            end
           
           
           % otherwise leave in waiting
        end

        % Button pushed function: UpdateButton_3
        function UpdateButton_3Pushed(app, event)
            % tw2lock, lockcmdthresh, holdcycles, disablelocks, patternchange
            % 1F57 11,12,13,16, dec 15,16,17, and 1F53 16
             temp=uint8([app.Tw2Lock.Value,app.LockCmdThresh.Value,app.HoldCyclesLock.Value]); % this makes temp and array of uint8
            if ~app.nnp.write(7,'1F57',11,temp,'uint8',3)
                app.TopMessage('Lockpar');
            else
                app.TopMessage('Error Lockpar');
            end

        end

        % Button pushed function: UpdateButton_4
        function UpdateButton_4Pushed(app, event)
            %pgain, poffset
            % 1F57 1,2
            if ~app.nnp.write(7,'1F57',2,uint8(app.Pgain.Value*10),'uint8')
                app.TopMessage('gain');
            else
                app.TopMessage('Error gain');
            end
            if ~app.nnp.write(7,'1F57',1,uint8(app.Poffset.Value),'uint8')
                app.TopMessage('offset');
            else
                app.TopMessage('Error offset');
            end
            app.refreshWFLylines();
        end

        % Button pushed function: UpdateButton_5
        function UpdateButton_5Pushed(app, event)
            %  D5high, Q
            % 1F57 5,6
            if ~app.nnp.write(7,'1F57',5,uint8(app.D5high.Value/10),'uint8')
                app.TopMessage('D5high 1');
                app.refreshD5ylines();
            else
                app.TopMessage('Error D5high 1');
            end
            if ~app.nnp.write(7,'1F57',6,uint8(app.Quiet.Value),'uint8')
                app.TopMessage('Quiet 1');
            else
                app.TopMessage('Error Quiet 1');
            end
        end

        % Button pushed function: UpdateButton_6
        function UpdateButton_6Pushed(app, event)
            %  D5high, Q
            % 1F57 7,8
            if ~app.nnp.write(7,'1F57',7,uint8(app.D5high_2.Value/10),'uint8')
                app.TopMessage('D5high 2');
                app.refreshD5ylines();
            else
                app.TopMessage('Error D5high 2');
            end
            if ~app.nnp.write(7,'1F57',8,uint8(app.Quiet_2.Value),'uint8')
                app.TopMessage('Quiet 2');
            else
                app.TopMessage('Error Quiet 2');
            end
        end

        % Button pushed function: UpdateButton_7
        function UpdateButton_7Pushed(app, event)
            %not implemented yet
        end

        % Button pushed function: UpdateButton_8
        function UpdateButton_8Pushed(app, event)
            temp=uint8(app.DisableLocks.Value);
            if ~app.nnp.write(7,'1F57',16,temp,'uint8') % note this is 1F57
                app.TopMessage('disablelock');
            else
                app.TopMessage('Error disablelock');
            end
            %   temp=uint8(app.AllowPatternChange.Value);
            temp=uint8(app.PatternChangeDropDown.Value);
            if ~app.nnp.write(7,'1F57',106,temp,'uint8')  % note this is 1F57 (previously was different location)
                app.TopMessage('PatternChange');
            else
                app.TopMessage('Error PatternChange');
            end
            temp=uint8(app.CommandToggleDropDown.Value);
            if ~app.nnp.write(7,'1F57',107,temp,'uint8')  % note this is 1F57 (previously was different location)
                app.TopMessage('Command Toggle Options');
            else
                app.TopMessage('Error Command Toggle Options');
            end
        end

        % Button pushed function: setXButton
        function setXButtonPushed(app, event)
            app.setDefaultXlimits();
        end

        % Button pushed function: setYButton
        function setYButtonPushed(app, event)
            app.setDefaultYlimits();
        end

        % Value changed function: ToolsButton
        function ToolsButtonValueChanged(app, event)
            value = app.ToolsButton.Value; % 0 or 1
            switch value
                case 0
                    sh='off';
                    app.ToolsButton.BackgroundColor=[0.96, 0.96, 0.96];
                case 1
                    sh='on';
                    app.ToolsButton.BackgroundColor=[0.67, 0.96, 0.67];
            end
            app.showhideAxesToolbars(sh);
        end

        % Button pushed function: NewLogFile
        function NewLogFileButtonPushed(app, event)
            app.GetNewFileNameFromDialog();
        end

        % Value changed function: LogtofileButton
        function LogtofileButtonValueChanged(app, event)
            value = app.LogtofileButton.Value;
            
            % When the logtofile button is pushed,
            %check for a valid file ID, then open the file in append mode.
            
            if value>0   %Start to log
                app.LogtofileButton.BackgroundColor='g';
                app.LogtofileButton.Text=sprintf('Log is On');
                % add a check here for a valid folder and call
                % FolderButtonPushed if not
                if ~isfolder(app.FolderEditField.Value)
                    app.GetNewFileNameFromDialog();
                end
                % Add check for valid file FID, and see if it is open
                % if not, then check for valid filename and create on if
                % not and open the file in append mode.
                if ~isfile(app.LogFileName)
                    app.GetNewFileNameFromDialog();
                end
                
                % check whether file ID exists
                if isempty(fopen(app.LFileID)) % check whether file is already open, 1 means not open
                    %file is not yet open so do the following:
                    app.LFileID=fopen(app.LogFileName,'a'); % open file in append mode
                    % Since file is newly opened, then write header.
                    header=app.makeHeader;
                    fprintf(app.LFileID,header);
                end
            else   % end the log
                app.LogtofileButton.BackgroundColor=[.96,.96,.96];
                app.LogtofileButton.Text=sprintf('Log is Off');
                % Note that I don't close the file here because I may want
                % to append to it.
                app.NewLogFile.Enable=1;
                app.FcloseButton.Enable=1;
            end
            
        end

        % Button pushed function: ToggleButton
        function ToggleButtonPushed(app, event)
            resp2=app.nnp.write(7,'1F53',4,uint8(26),'uint8');
            if resp2==0
                message='Lock Toggle';
            else
                message='Lock Toggle  failed';
            end
            app.TopMessage(message);
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            % open mat file to read in emg parameter data
            % start with a mat file that I create externally to see if I have all the necessary variables saved.
            
            % add this later
            %             if app.checkDoSave()
            %                 return
            %             end
            
            [filename, pathname ] = uigetfile('*.mat', 'Open EMG parameters mat File');
            if filename == 0
                return
            else
                app.emgFilename = [pathname filename];
                app.ParamFileEditField.Value=app.emgFilename;
            end
            
            in=load(app.emgFilename);
            if ~isfield(in, 'emgParameters') % this file does not contain the right structure
                % here I could test also test the number of fields in rmParam
                msgbox('EMG Parameter  mat file must contain the structure variable named "emgParameters". Please try again.');
            else
                app.emgParameters=in.emgParameters; % copy to app
                app.BPnode.Value=in.bpNode;
                app.updateEMGParameters();
            end
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
            % get a file name and save the data to that file
            % include a default file name.  How about emgParameters.mat
            [filename, pathname ] = uiputfile('*.mat', 'Save As EMG parameters mat File','emgParameters.mat');
            if filename == 0
                return
            else
                app.emgFilename = fullfile(pathname, filename);
                SaveMenuSelected(app, event);
                app.pathname=pathname;
                app.ParamFileEditField.Value=app.emgFilename;
            end
            
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            %check for valid file name.   get new name if not valid
            % save data to file
            if isempty(app.emgFilename)
                SaveAsMenuSelected(app, event);
            else
                app.updateEMGParArray(); % fill the parameter array before writing
                bpNode=app.BPnode.Value;
                emgParameters=app.emgParameters; % save as local variable to save
                save(app.emgFilename,'emgParameters','bpNode'); % save the whole structure as a variable, rather than individual
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ishandle(app.callingFigure) % check that is is valid
                figure(app.callingFigure); % bring to front
            end
            
            delete(app)
            
        end

        % Cell edit callback: LockDisableTable
        function LockDisableTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.LockDisable=app.LockDisableTable.Data;
            app.DisableLocks.Value=app.LockTable2Dec(app.LockDisable);
            % need callback to update LockDisable variable and table when DisableLocks is changed
        end

        % Value changed function: DisableLocks
        function DisableLocksValueChanged(app, event)
            value = app.DisableLocks.Value;
            %Need function to go other direction
            app.LockDisable=app.Dec2LockTable(value); % update lock table variable
            app.LockDisableTable.Data=app.LockDisable; % update the table
        end

        % Value changed function: CommandToggleDropDown
        function CommandToggleDropDownValueChanged(app, event)
            value = app.CommandToggleDropDown.Value;
            enableButtons=(value>0);
           app.CommandToggleTestButton.Enable=enableButtons;
           app.CommandToggleResetButton.Enable=enableButtons;
        end

        % Button pushed function: CommandToggleTestButton
        function CommandToggleTestButtonPushed(app, event)
            if ~app.nnp.write(7,'1F53',81,1,'uint8')  % N7:1F53.51, Hand command toggle, 0= none; 1=toggle; 2=reset
                app.TopMessage('Toggle Hand Command');
            else
                app.TopMessage('Error Toggle Hand Command');
            end
        end

        % Button pushed function: CommandToggleResetButton
        function CommandToggleResetButtonPushed(app, event)
            if ~app.nnp.write(7,'1F53',81,2,'uint8')  % N7:1F53.51, Hand command toggle, 0= none; 1=toggle; 2=reset
                app.TopMessage('Toggle Hand Command');
            else
                app.TopMessage('Error Toggle Hand Command');
            end
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            %force stop
            app.ViewEMGButton.Value=false; %stop recording
            pause(1);
            app.ViewEMGButtonValueChanged([]);
        end

        % Button pushed function: SavePMODButton
        function SavePMODButtonPushed(app, event)
            if app.nnp.saveOD(7)
                    dmessage = 'PM OD Saved';
            else
                dmessage = 'Failed PM OD Save';
            end
            app.TopMessage(dmessage);
        end

        % Button pushed function: SaveBPODButton
        function SaveBPODButtonPushed(app, event)
           % need to be in waiting mode to save the OD
           if app.ViewEMGButton.Value % need to stop recording first
                app.ViewEMGButton.Value=false; %stop recording
                pause(1);
                app.ViewEMGButtonValueChanged([]);
                %will the buttonchange function run or do I need to
                %manually change?
                pause(1);
            end
            currentMode=uint8(0);
            resp = app.nnp.read(7, '1F53', 38,'uint8'); % nodeState
            if length(resp)==1
                currentMode=resp;
            else
                app.TopMessage('current Mode not read');
                app.TopMessage(dmessage);
            end
            
            % if n=mode is not in waiting
            if currentMode~=1
                if ~app.nnp.enterWaiting() % put into waiting mode
                    app.TopMessage('could not enter Waiting');
                end
            end
            if app.nnp.saveOD(9)
                dmessage = 'BP OD Saved';
            else
                dmessage = 'Failed BP OD Save';
            end
            app.TopMessage(dmessage);
            pause(.2); % allow save to finish
            % if currentMode is patient
            if currentMode==5
                if ~app.nnp.enterPatient()
                    app.TopMessage('could not enter Patient mode');
                else
                    app.TopMessage('back in Patient Mode');
                end
                app.TopMessage(dmessage);
            end
           % otherwise leave in waiting

        end

        % Selection change function: ParGroup
        function ParGroupSelectionChanged(app, event)
            selectedTab = app.ParGroup.SelectedTab;
            if selectedTab==app.StandTab
                app.useTrunkCommand=true;

            else
                app.useTrunkCommand=false;
            end
           app.refreshWFLylines();     
        end

        % Button pushed function: UpdateButton_9
        function UpdateButton_9Pushed(app, event)
            %pgain, poffset
            % 1F57 3,4
            if ~app.nnp.write(7,'1F57',4,uint8(app.Pgain_2.Value*10),'uint8')
                app.TopMessage('gain2');
            else
                app.TopMessage('Error gain2');
            end
            if ~app.nnp.write(7,'1F57',3,uint8(app.Poffset_2.Value),'uint8')
                app.TopMessage('offset2');
            else
                app.TopMessage('Error offset2');
            end
            app.refreshWFLylines();
        end

        % Button pushed function: StartStandingTransferButton
        function StartStandingTransferButtonPushed(app, event)
            resp = questdlg('Start Standing Transfer Script?');
            if ~isequal(resp, 'Yes')
                return;
            end
             % probably best to stop network first or send stop ADL first.
             %BOOKMARK2
             % first reset by stopping the network
             if ~app.stopADL()
                 app.TopMessage('stop ADL failed');
                 return;
             else
                 app.TopMessage('stop ADL');
             end
             % Then start the Hand Control script
             pause(1);
             resp=app.nnp.write(7,'1F53',4,uint8(23),'uint8');
             %success=(resp==0);
             if resp==0
                 message='Start Stand Transfer';
             else
                 message=['Stand',' failed'];
             end
             app.TopMessage(message);
             
        end

        % Value changed function: GainSetADropDown
        function GainSetADropDownValueChanged(app, event)
            value = app.GainSetADropDown.Value;
            % when the value changes, set the BP gain and WFL gain, then
            % run the change script button press already in place
            pairs=[3,20;4,21;5,23;6,24;7,26;8,28;9,30;10,32;11,35;12,37;13,40;12,43]; %gain,wflgain pairs
            
            switch value
                case '-'
                    %do nothing
                otherwise
                    newpair=pairs(str2num(value)-1,:);
                    app.HWgain.Value=newpair(1);
                    app.WFLgain.Value=newpair(2);
                    app.UpdateButtonPushed();
            end


        end

        % Value changed function: GainSetBDropDown
        function GainSetBDropDownValueChanged(app, event)
            value = app.GainSetBDropDown.Value;
            % when the value changes, set the BP gain and WFL gain, then
            % run the change script button press already in place
            pairs=[3,20;4,21;5,23;6,24;7,26;8,28;9,30;10,32;11,35;12,37;13,40;12,43]; %gain,wflgain pairs
            
            switch value
                case '-'
                    %do nothing
                otherwise
                    newpair=pairs(str2num(value)-1,:);
                    app.HWgain_2.Value=newpair(1);
                    app.WFLgain_2.Value=newpair(2);
                    app.UpdateButton_2Pushed();
            end
        end

        % Value changed function: UpperTh
        function UpperThValueChanged(app, event)
             app.refreshWFLylines();
            
        end

        % Value changed function: LowerTh
        function LowerThValueChanged(app, event)
             app.refreshWFLylines();
            
        end

        % Value changed function: BPEMGChannelConnectionsDropDown
        function BPEMGChannelConnectionsDropDownValueChanged(app, event)
            value = app.BPEMGChannelConnectionsDropDown.Value;
            if strcmp(value,'Normal')
                resp=app.nnp.write(7,'1F57',119,uint8(0),'uint8'); % Not reversed inputs
                if resp==0 % success
                    message='EMG Input Channels Normal';
                else
                    message=['Could not set EMG input channels'];
                end
                app.BPEMGChannelConnectionsDropDown.BackgroundColor=[0.96,0.96,0.96];
                app.BPEMGChannelConnectionsDropDown.FontColor=[0,0,0];
            else % Reversed
                resp=app.nnp.write(7,'1F57',119,uint8(1),'uint8'); % Reversed inputs
                if resp==0 % success
                    message='EMG Input Channels Resersed';
                else
                    message=['Could not set EMG input channels'];
                end
                app.BPEMGChannelConnectionsDropDown.BackgroundColor=[0.2,0.2,0.2];
                app.BPEMGChannelConnectionsDropDown.FontColor=[1,1,1];
            end
            app.TopMessage(message);
        end

        % Value changed function: LockTwitchChannelDropDown
        function LockTwitchChannelDropDownValueChanged(app, event)
            value = app.LockTwitchChannelDropDown.Value;
            if value == 'EMG-A'
                app.LockTwitchChannelDropDown.BackgroundColor=[0.91,0.96,0.99];
            elseif value == 'EMG-B'
                app.LockTwitchChannelDropDown.BackgroundColor=[0.99,0.99,0.85];
            else
                 app.LockTwitchChannelDropDown.BackgroundColor=[0.96,0.96,0.96];  
            end
        end

        % Value changed function: UnLockDoubletChannelDropDown
        function UnLockDoubletChannelDropDownValueChanged(app, event)
            value = app.UnLockDoubletChannelDropDown.Value;
           if value == 'EMG-A'
                app.UnLockDoubletChannelDropDown.BackgroundColor=[0.91,0.96,0.99];
            elseif value == 'EMG-B'
                app.UnLockDoubletChannelDropDown.BackgroundColor=[0.99,0.99,0.85];
            else
                 app.UnLockDoubletChannelDropDown.BackgroundColor=[0.96,0.96,0.96];  
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Record Processed EMG';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.OpenMenu = uimenu(app.Menu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.Menu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.Menu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Text = 'SaveAs';
            app.EMGAxes = uiaxes(app.UIFigure);
            ylabel(app.EMGAxes, 'WFL')
            app.EMGAxes.XTick = [0 10 20 30 40 50];
            app.EMGAxes.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.EMGAxes.YTick = [0 50 100 150 200 250 300];
            app.EMGAxes.YTickLabel = {'0'; '50'; '100'; '150'; '200'; '250'; '300'};
            app.ScaledAxes = uiaxes(app.UIFigure);
            ylabel(app.ScaledAxes, 'Scaled')
            app.ScaledAxes.XTick = [0 10 20 30 40 50];
            app.ScaledAxes.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.ScaledAxes.YTick = [0 50 100 150 200 250 300];
            app.ScaledAxes.YTickLabel = {'0'; '50'; '100'; '150'; '200'; '250'; '300'};
            app.EMGAxes_2 = uiaxes(app.UIFigure);
            ylabel(app.EMGAxes_2, 'WFL')
            app.EMGAxes_2.XTick = [0 10 20 30 40 50];
            app.EMGAxes_2.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.EMGAxes_2.YTick = [0 50 100 150 200 250 300];
            app.EMGAxes_2.YTickLabel = {'0'; '50'; '100'; '150'; '200'; '250'; '300'};
            app.ScaledAxes_2 = uiaxes(app.UIFigure);
            ylabel(app.ScaledAxes_2, 'Scaled')
            app.ScaledAxes_2.XTick = [0 10 20 30 40 50];
            app.ScaledAxes_2.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.ScaledAxes_2.YTick = [0 50 100 150 200 250 300];
            app.ScaledAxes_2.YTickLabel = {'0'; '50'; '100'; '150'; '200'; '250'; '300'};
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LogicalTab = uitab(app.TabGroup);
            app.LogicalTab.Title = 'Logical';
            app.D5Axes = uiaxes(app.LogicalTab);
            ylabel(app.D5Axes, 'D5')
            app.D5Axes.XTick = [0 10 20 30 40 50];
            app.D5Axes.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.TwitchAxes = uiaxes(app.LogicalTab);
            xlabel(app.TwitchAxes, 'time (s)')
            ylabel(app.TwitchAxes, 'Twitch')
            app.TwitchAxes.XTick = [0 10 20 30 40 50];
            app.TwitchAxes.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.TwitchAxes.YTick = [-0.1 1 2 4];
            app.TwitchAxes.YTickLabel = {'0'; '1'; '2'; '4'};
            app.TwitchAxes.ZTick = [];
            app.HLAxes = uiaxes(app.LogicalTab);
            xlabel(app.HLAxes, 'time (s)')
            ylabel(app.HLAxes, 'HL Count')
            app.HLAxes.XTick = [0 10 20 30 40 50];
            app.HLAxes.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.HLAxes.YTick = [-0.1 5 10 15 20 25];
            app.HLAxes.YTickLabel = {'0'; '5'; '10'; '15'; '20'; '25'};
            app.HLAxes.ZTick = [];
            app.ProportionalTab = uitab(app.TabGroup);
            app.ProportionalTab.Title = 'Proportional';
            app.ASFAxes = uiaxes(app.ProportionalTab);
            ylabel(app.ASFAxes, 'ASF')
            app.ASFAxes.XTick = [0 10 20 30 40 50];
            app.ASFAxes.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.ASFAxes.YTick = [0 50 100 150 200 250 300];
            app.ASFAxes.YTickLabel = {'0'; '50'; '100'; '150'; '200'; '250'; '300'};
            app.TabGroup_2 = uitabgroup(app.UIFigure);
            app.LogicalTab_2 = uitab(app.TabGroup_2);
            app.LogicalTab_2.Title = 'Logical';
            app.D5Axes_2 = uiaxes(app.LogicalTab_2);
            ylabel(app.D5Axes_2, 'D5')
            app.D5Axes_2.XTick = [0 10 20 30 40 50];
            app.D5Axes_2.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.TwitchAxes_2 = uiaxes(app.LogicalTab_2);
            xlabel(app.TwitchAxes_2, 'time (s)')
            ylabel(app.TwitchAxes_2, 'Twitch')
            app.TwitchAxes_2.XTick = [0 10 20 30 40 50];
            app.TwitchAxes_2.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.TwitchAxes_2.YTick = [-0.1 1 2 4];
            app.TwitchAxes_2.YTickLabel = {'0'; '1'; '2'; '4'};
            app.TwitchAxes_2.ZTick = [];
            app.HLAxes_2 = uiaxes(app.LogicalTab_2);
            xlabel(app.HLAxes_2, 'time (s)')
            ylabel(app.HLAxes_2, 'HL Count')
            app.HLAxes_2.XTick = [0 10 20 30 40 50];
            app.HLAxes_2.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.HLAxes_2.YTick = [-0.1 5 10 15 20 25];
            app.HLAxes_2.YTickLabel = {'0'; '5'; '10'; '15'; '20'; '25'};
            app.HLAxes_2.ZTick = [];
            app.ProportionalTab_2 = uitab(app.TabGroup_2);
            app.ProportionalTab_2.Title = 'Proportional';
            app.ASFAxes_2 = uiaxes(app.ProportionalTab_2);
            ylabel(app.ASFAxes_2, 'ASF')
            app.ASFAxes_2.XTick = [0 10 20 30 40 50];
            app.ASFAxes_2.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.ASFAxes_2.YTick = [0 50 100 150 200 250 300];
            app.ASFAxes_2.YTickLabel = {'0'; '50'; '100'; '150'; '200'; '250'; '300'};
            app.ComAxes_2 = uiaxes(app.ProportionalTab_2);
            ylabel(app.ComAxes_2, 'Command')
            app.ComAxes_2.XTick = [0 10 20 30 40 50];
            app.ComAxes_2.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.ComAxes_2.YTick = [0 50 100 150 200 250 300];
            app.ComAxes_2.YTickLabel = {'0'; '50'; '100'; '150'; '200'; '250'; '300'};
            app.ParGroup = uitabgroup(app.UIFigure);
            app.ParGroup.SelectionChangedFcn = createCallbackFcn(app, @ParGroupSelectionChanged, true);
            app.LogTab = uitab(app.ParGroup);
            app.LogTab.Tooltip = {'In order for the processed EMG to display, two things need to happen:'; ''; '1) The Network must be ON and running in Patient mode'; ''; '2) The EMG processing scripts must be running'; ''; 'If the parameters are set correctly, these are both accomplished by running either the Hand ADL script or the MES with No Stim options in the Parameters tab under “Scripts”'; ''};
            app.LogTab.Title = 'Log';
            app.LogtofileButton = uibutton(app.LogTab, 'state');
            app.LogtofileButton.ValueChangedFcn = createCallbackFcn(app, @LogtofileButtonValueChanged, true);
            app.LogtofileButton.Text = 'Log is Off';
            app.NewLogFile = uibutton(app.LogTab, 'push');
            app.NewLogFile.ButtonPushedFcn = createCallbackFcn(app, @NewLogFileButtonPushed, true);
            app.NewLogFile.Text = {'New'; 'Log File'};
            app.FcloseButton = uibutton(app.LogTab, 'push');
            app.FcloseButton.Text = {'Close'; 'File'};
            app.FolderEditField = uieditfield(app.LogTab, 'text');
            app.LogFileNameEditField = uieditfield(app.LogTab, 'text');
            app.CommentFileNameEditField = uieditfield(app.LogTab, 'text');
            app.CommentsTextArea = uitextarea(app.LogTab);
            app.CommentsTextAreaLabel = uilabel(app.LogTab);
            app.CommentsTextAreaLabel.Text = 'Comments';
            app.ifEMGnotdisplayedcheckScriptOrderMESSyncTimingPDOsetupLabel = uilabel(app.LogTab);
            app.ifEMGnotdisplayedcheckScriptOrderMESSyncTimingPDOsetupLabel.Text = {'if EMG not displayed, check:'; '--- Corrent Scripts Running'; '----Script Order'; '--- MES Sync Timing'; '--- PDO setup'};
            app.UserIDEditField = uieditfield(app.LogTab, 'text');
            app.UserIDEditField.Editable = 'off';
            app.ParameterTab = uitab(app.ParGroup);
            app.ParameterTab.Title = 'Parameter';
            app.EMGWFLAPanel = uipanel(app.ParameterTab);
            app.EMGWFLAPanel.Title = 'EMG WFL A';
            app.HWgainLabel = uilabel(app.EMGWFLAPanel);
            app.HWgainLabel.Text = 'HWgain';
            app.HWgain = uieditfield(app.EMGWFLAPanel, 'numeric');
            app.HWgain.Value = 1;
            app.DepthLabel = uilabel(app.EMGWFLAPanel);
            app.DepthLabel.Text = 'Depth';
            app.Depth = uieditfield(app.EMGWFLAPanel, 'numeric');
            app.Depth.Value = 1;
            app.WFLgainLabel = uilabel(app.EMGWFLAPanel);
            app.WFLgainLabel.Text = 'WFLgain';
            app.WFLgain = uieditfield(app.EMGWFLAPanel, 'numeric');
            app.WFLgain.Value = 40;
            app.UpdateButton = uibutton(app.EMGWFLAPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Tooltip = {'Send WFL1 parameters'};
            app.UpdateButton.Text = 'A';
            app.EMGWFLBPanel = uipanel(app.ParameterTab);
            app.EMGWFLBPanel.Title = 'EMG WFL B';
            app.HWgainLabel_2 = uilabel(app.EMGWFLBPanel);
            app.HWgainLabel_2.Text = 'HWgain';
            app.HWgain_2 = uieditfield(app.EMGWFLBPanel, 'numeric');
            app.HWgain_2.Value = 1;
            app.DepthLabel_2 = uilabel(app.EMGWFLBPanel);
            app.DepthLabel_2.Text = 'Depth';
            app.Depth_2 = uieditfield(app.EMGWFLBPanel, 'numeric');
            app.Depth_2.Value = 1;
            app.WFLgainLabel_2 = uilabel(app.EMGWFLBPanel);
            app.WFLgainLabel_2.Text = 'WFLgain';
            app.WFLgain_2 = uieditfield(app.EMGWFLBPanel, 'numeric');
            app.WFLgain_2.Value = 40;
            app.UpdateButton_2 = uibutton(app.EMGWFLBPanel, 'push');
            app.UpdateButton_2.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_2Pushed, true);
            app.UpdateButton_2.Tooltip = {'Send WFL2 parameters'};
            app.UpdateButton_2.Text = 'B';
            app.TwitchParametersPanel_1 = uipanel(app.ParameterTab);
            app.TwitchParametersPanel_1.Title = 'Twitch Parameters 1';
            app.EnableDoublet = uibutton(app.TwitchParametersPanel_1, 'push');
            app.EnableDoublet.ButtonPushedFcn = createCallbackFcn(app, @EnableDoubletButtonPushed, true);
            app.EnableDoublet.Text = {'Enable'; 'Doublet'};
            app.DisableDouble = uibutton(app.TwitchParametersPanel_1, 'push');
            app.DisableDouble.ButtonPushedFcn = createCallbackFcn(app, @DisableDoubleButtonPushed, true);
            app.DisableDouble.Text = {'Disable'; 'Doublet'};
            app.D5highLabel = uilabel(app.TwitchParametersPanel_1);
            app.D5highLabel.Text = 'D5high';
            app.D5high = uieditfield(app.TwitchParametersPanel_1, 'numeric');
            app.D5high.Value = 200;
            app.QuietLabel = uilabel(app.TwitchParametersPanel_1);
            app.QuietLabel.Text = 'Quiet';
            app.Quiet = uieditfield(app.TwitchParametersPanel_1, 'numeric');
            app.UpdateButton_5 = uibutton(app.TwitchParametersPanel_1, 'push');
            app.UpdateButton_5.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_5Pushed, true);
            app.UpdateButton_5.Tooltip = {'Send Twitch 1 parameters'};
            app.UpdateButton_5.Text = '5';
            app.TwitchParametersPanel_2 = uipanel(app.ParameterTab);
            app.TwitchParametersPanel_2.Title = 'Twitch Parameters 2';
            app.QuietLabel_2 = uilabel(app.TwitchParametersPanel_2);
            app.QuietLabel_2.Text = 'Quiet';
            app.Quiet_2 = uieditfield(app.TwitchParametersPanel_2, 'numeric');
            app.D5highLabel_2 = uilabel(app.TwitchParametersPanel_2);
            app.D5highLabel_2.Text = 'D5high';
            app.D5high_2 = uieditfield(app.TwitchParametersPanel_2, 'numeric');
            app.D5high_2.Value = 200;
            app.EnableDoublet_2 = uibutton(app.TwitchParametersPanel_2, 'push');
            app.EnableDoublet_2.ButtonPushedFcn = createCallbackFcn(app, @EnableDoublet_2ButtonPushed, true);
            app.EnableDoublet_2.Text = {'Enable'; 'Doublet'};
            app.DisableDouble_2 = uibutton(app.TwitchParametersPanel_2, 'push');
            app.DisableDouble_2.ButtonPushedFcn = createCallbackFcn(app, @DisableDouble_2ButtonPushed, true);
            app.DisableDouble_2.Text = {'Disable'; 'Doublet'};
            app.UpdateButton_6 = uibutton(app.TwitchParametersPanel_2, 'push');
            app.UpdateButton_6.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_6Pushed, true);
            app.UpdateButton_6.Tooltip = {'Send Twitch 2 parameters'};
            app.UpdateButton_6.Text = '6';
            app.LogicParametersPanel = uipanel(app.ParameterTab);
            app.LogicParametersPanel.Title = 'Logic Parameters';
            app.HoldCyclesLabel = uilabel(app.LogicParametersPanel);
            app.HoldCyclesLabel.Text = 'HoldCycles';
            app.HoldCyclesLock = uieditfield(app.LogicParametersPanel, 'numeric');
            app.HoldCyclesLock.Value = 15;
            app.LockCmdThLabel = uilabel(app.LogicParametersPanel);
            app.LockCmdThLabel.Text = 'LockCmdTh';
            app.LockCmdThresh = uieditfield(app.LogicParametersPanel, 'numeric');
            app.LockCmdThresh.Value = 200;
            app.Tw2LockLabel = uilabel(app.LogicParametersPanel);
            app.Tw2LockLabel.Text = 'Tw2Lock';
            app.Tw2Lock = uieditfield(app.LogicParametersPanel, 'numeric');
            app.DisableLocksButton = uibutton(app.LogicParametersPanel, 'push');
            app.DisableLocksButton.ButtonPushedFcn = createCallbackFcn(app, @DisableLocksButtonPushed, true);
            app.DisableLocksButton.Tooltip = {'Save current setting to temporary value and set value to 01'};
            app.DisableLocksButton.Text = {'Disable'; 'Locks'};
            app.RestoreLocksButton = uibutton(app.LogicParametersPanel, 'push');
            app.RestoreLocksButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreLocksButtonPushed, true);
            app.RestoreLocksButton.Tooltip = {'retore from temporary backup value'};
            app.RestoreLocksButton.Text = {'Restore'; 'Locks'};
            app.UpdateButton_3 = uibutton(app.LogicParametersPanel, 'push');
            app.UpdateButton_3.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_3Pushed, true);
            app.UpdateButton_3.Tooltip = {'Send Lock parameters'};
            app.UpdateButton_3.Text = '3';
            app.ObjectDictionaryPanel = uipanel(app.ParameterTab);
            app.ObjectDictionaryPanel.Title = 'Object Dictionary';
            app.ModulesDropDown = uidropdown(app.ObjectDictionaryPanel);
            app.ModulesDropDown.Items = {'Read OD EMG Par', 'RM Status', 'Write OD EMG Par', 'Save PM OD', 'Save BP OD'};
            app.ModulesDropDown.ItemsData = {'1', '2', '3'};
            app.ModulesDropDown.Value = '1';
            app.ODGOButton = uibutton(app.ObjectDictionaryPanel, 'push');
            app.ODGOButton.ButtonPushedFcn = createCallbackFcn(app, @ODGOButtonPushed, true);
            app.ODGOButton.Text = 'Go';
            app.SavePMODButton = uibutton(app.ObjectDictionaryPanel, 'push');
            app.SavePMODButton.ButtonPushedFcn = createCallbackFcn(app, @SavePMODButtonPushed, true);
            app.SavePMODButton.Tooltip = {'Save current PM object dictionary as default'};
            app.SavePMODButton.Text = 'Save PM OD';
            app.BlueovalssendparameterstoODinPMandRMLabel = uilabel(app.ParameterTab);
            app.BlueovalssendparameterstoODinPMandRMLabel.Text = 'Blue ovals send parameters to OD in PM and RM';
            app.PlotLimitsPanel = uipanel(app.ParameterTab);
            app.PlotLimitsPanel.Title = 'Plot Limits';
            app.setXButton = uibutton(app.PlotLimitsPanel, 'push');
            app.setXButton.ButtonPushedFcn = createCallbackFcn(app, @setXButtonPushed, true);
            app.setXButton.Tooltip = {'default X scales'};
            app.setXButton.Text = 'X';
            app.setYButton = uibutton(app.PlotLimitsPanel, 'push');
            app.setYButton.ButtonPushedFcn = createCallbackFcn(app, @setYButtonPushed, true);
            app.setYButton.Tooltip = {'default Y scales'};
            app.setYButton.Text = 'Y';
            app.ToolsButton = uibutton(app.PlotLimitsPanel, 'state');
            app.ToolsButton.ValueChangedFcn = createCallbackFcn(app, @ToolsButtonValueChanged, true);
            app.ToolsButton.Tooltip = {'Plot tools on each graph'};
            app.ToolsButton.Text = 'Tools';
            app.ScriptsPanel = uipanel(app.ParameterTab);
            app.ScriptsPanel.Title = 'Scripts';
            app.Script = uidropdown(app.ScriptsPanel);
            app.Script.Items = {'Startup', 'MES test', 'Hand ADL 1', 'Hand ADL 2', 'MES with No Stim', 'Stop ADL/Net'};
            app.Script.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.Script.Value = '1';
            app.ActionGo = uibutton(app.ScriptsPanel, 'push');
            app.ActionGo.ButtonPushedFcn = createCallbackFcn(app, @ActionGoButtonPushed, true);
            app.ActionGo.Text = 'Go';
            app.SaveBPODButton = uibutton(app.ParameterTab, 'push');
            app.SaveBPODButton.ButtonPushedFcn = createCallbackFcn(app, @SaveBPODButtonPushed, true);
            app.SaveBPODButton.Tooltip = {'Save current PM object dictionary as default'};
            app.SaveBPODButton.Text = 'Save BP OD';
            app.ScalePanel = uipanel(app.ParameterTab);
            app.ScalePanel.Title = 'Scale';
            app.UpdateButton_4 = uibutton(app.ScalePanel, 'push');
            app.UpdateButton_4.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_4Pushed, true);
            app.UpdateButton_4.Tooltip = {'Send proportional gain parameters'};
            app.UpdateButton_4.Text = '4';
            app.PoffsetLabel = uilabel(app.ScalePanel);
            app.PoffsetLabel.Enable = 'off';
            app.PoffsetLabel.Text = 'Poffset';
            app.Poffset = uieditfield(app.ScalePanel, 'numeric');
            app.Poffset.ValueChangedFcn = createCallbackFcn(app, @PoffsetValueChanged, true);
            app.Poffset.Editable = 'off';
            app.Poffset.Enable = 'off';
            app.Poffset.Tooltip = {'value in OD is 10x'};
            app.PgainLabel = uilabel(app.ScalePanel);
            app.PgainLabel.Enable = 'off';
            app.PgainLabel.Text = 'Pgain';
            app.Pgain = uieditfield(app.ScalePanel, 'numeric');
            app.Pgain.ValueChangedFcn = createCallbackFcn(app, @PgainValueChanged, true);
            app.Pgain.Enable = 'off';
            app.Pgain.Value = 1;
            app.PgainLabel_3 = uilabel(app.ScalePanel);
            app.PgainLabel_3.Text = 'Upper';
            app.UpperTh = uieditfield(app.ScalePanel, 'numeric');
            app.UpperTh.ValueChangedFcn = createCallbackFcn(app, @UpperThValueChanged, true);
            app.UpperTh.Value = 200;
            app.PoffsetLabel_3 = uilabel(app.ScalePanel);
            app.PoffsetLabel_3.Text = 'Lower';
            app.LowerTh = uieditfield(app.ScalePanel, 'numeric');
            app.LowerTh.ValueChangedFcn = createCallbackFcn(app, @LowerThValueChanged, true);
            app.LowerTh.Tooltip = {'value in OD is 10x'};
            app.LowerTh.Value = 20;
            app.GainSetADropDownLabel = uilabel(app.ParameterTab);
            app.GainSetADropDownLabel.Text = 'GainSetA';
            app.GainSetADropDown = uidropdown(app.ParameterTab);
            app.GainSetADropDown.Items = {'-', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11'};
            app.GainSetADropDown.ValueChangedFcn = createCallbackFcn(app, @GainSetADropDownValueChanged, true);
            app.GainSetADropDown.Value = '-';
            app.GainSetBDropDownLabel = uilabel(app.ParameterTab);
            app.GainSetBDropDownLabel.Text = 'GainSetB';
            app.GainSetBDropDown = uidropdown(app.ParameterTab);
            app.GainSetBDropDown.Items = {'-', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11'};
            app.GainSetBDropDown.ValueChangedFcn = createCallbackFcn(app, @GainSetBDropDownValueChanged, true);
            app.GainSetBDropDown.Value = '-';
            app.OptionsTab = uitab(app.ParGroup);
            app.OptionsTab.Title = 'Options';
            app.LockTwitchChannelDropDownLabel = uilabel(app.OptionsTab);
            app.LockTwitchChannelDropDownLabel.Text = 'Lock Twitch Channel';
            app.LockTwitchChannelDropDown = uidropdown(app.OptionsTab);
            app.LockTwitchChannelDropDown.Items = {'EMG-A', 'EMG-B', 'Unknown'};
            app.LockTwitchChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @LockTwitchChannelDropDownValueChanged, true);
            app.LockTwitchChannelDropDown.Tooltip = {'Read/Written with OD Parameters'};
            app.LockTwitchChannelDropDown.Value = 'EMG-A';
            app.UnLockDoubletChannelDropDownLabel = uilabel(app.OptionsTab);
            app.UnLockDoubletChannelDropDownLabel.Text = 'UnLock Doublet Channel';
            app.UnLockDoubletChannelDropDown = uidropdown(app.OptionsTab);
            app.UnLockDoubletChannelDropDown.Items = {'EMG-A', 'EMG-B', 'Unknown'};
            app.UnLockDoubletChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @UnLockDoubletChannelDropDownValueChanged, true);
            app.UnLockDoubletChannelDropDown.Tooltip = {'Read/Written with OD Parameters'};
            app.UnLockDoubletChannelDropDown.Value = 'EMG-A';
            app.LockDisablePanel = uipanel(app.OptionsTab);
            app.LockDisablePanel.Tooltip = {'Select which patterns should have locks disabled,'};
            app.LockDisablePanel.Title = 'LockDisable';
            app.LockDisableTable = uitable(app.LockDisablePanel);
            app.LockDisableTable.ColumnName = '';
            app.LockDisableTable.RowName = {'All'; 'Pat 1'; 'Pat 2'; 'Pat 3'; 'Pat 4'; 'Pat 5'};
            app.LockDisableTable.ColumnEditable = true;
            app.LockDisableTable.CellEditCallback = createCallbackFcn(app, @LockDisableTableCellEdit, true);
            app.DisableLocksLabel = uilabel(app.LockDisablePanel);
            app.DisableLocksLabel.Text = 'DisableLocks';
            app.DisableLocks = uieditfield(app.LockDisablePanel, 'numeric');
            app.DisableLocks.ValueChangedFcn = createCallbackFcn(app, @DisableLocksValueChanged, true);
            app.DisableLocks.Tooltip = {'Bit 0=all, Bits 1-5 for Hand patterns 1-5'};
            app.CommandToggleDropDownLabel = uilabel(app.OptionsTab);
            app.CommandToggleDropDownLabel.Text = 'Command Toggle';
            app.CommandToggleDropDown = uidropdown(app.OptionsTab);
            app.CommandToggleDropDown.Items = {'None', 'Button Only', 'Button or Twitch', 'Twitch/Doublet'};
            app.CommandToggleDropDown.ValueChangedFcn = createCallbackFcn(app, @CommandToggleDropDownValueChanged, true);
            app.CommandToggleDropDown.Value = 'None';
            app.PatternChangeDropDownLabel = uilabel(app.OptionsTab);
            app.PatternChangeDropDownLabel.Text = 'Pattern Change';
            app.PatternChangeDropDown = uidropdown(app.OptionsTab);
            app.PatternChangeDropDown.Items = {'Buttons Only', 'Long Twitch or Buttons'};
            app.PatternChangeDropDown.Value = 'Buttons Only';
            app.UpdateButton_8 = uibutton(app.OptionsTab, 'push');
            app.UpdateButton_8.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_8Pushed, true);
            app.UpdateButton_8.Tooltip = {'send lock disable, pattern change and command toggle'};
            app.UpdateButton_8.Text = '8';
            app.CommandToggleTestButton = uibutton(app.OptionsTab, 'push');
            app.CommandToggleTestButton.ButtonPushedFcn = createCallbackFcn(app, @CommandToggleTestButtonPushed, true);
            app.CommandToggleTestButton.Enable = 'off';
            app.CommandToggleTestButton.Tooltip = {'Toggle Comand if Enabled'};
            app.CommandToggleTestButton.Text = {'Toggle'; 'Test'};
            app.CommandToggleResetButton = uibutton(app.OptionsTab, 'push');
            app.CommandToggleResetButton.ButtonPushedFcn = createCallbackFcn(app, @CommandToggleResetButtonPushed, true);
            app.CommandToggleResetButton.Enable = 'off';
            app.CommandToggleResetButton.Tooltip = {'Reset Command Toggle Logic'};
            app.CommandToggleResetButton.Text = 'Reset';
            app.PMversion405Label = uilabel(app.OptionsTab);
            app.PMversion405Label.Text = 'PM version 405+';
            app.SyncPanel = uipanel(app.OptionsTab);
            app.SyncPanel.Title = 'Sync';
            app.SyncLabel = uilabel(app.SyncPanel);
            app.SyncLabel.Text = 'Sync';
            app.Sync = uieditfield(app.SyncPanel, 'numeric');
            app.Sync.Value = 60;
            app.SyncIntLabel = uilabel(app.SyncPanel);
            app.SyncIntLabel.Text = 'SyncInt';
            app.SyncInt = uieditfield(app.SyncPanel, 'numeric');
            app.SyncInt.Value = 1;
            app.UpdateButton_7 = uibutton(app.SyncPanel, 'push');
            app.UpdateButton_7.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_7Pushed, true);
            app.UpdateButton_7.Tooltip = {'send sync parameters'};
            app.UpdateButton_7.Text = '7';
            app.BPnodeLabel = uilabel(app.SyncPanel);
            app.BPnodeLabel.Text = 'BP node';
            app.BPnode = uieditfield(app.SyncPanel, 'numeric');
            app.BPnode.Value = 9;
            app.Label_6 = uilabel(app.OptionsTab);
            app.Label_6.Text = '______________________________________';
            app.BPEMGChannelConnectionsDropDownLabel = uilabel(app.OptionsTab);
            app.BPEMGChannelConnectionsDropDownLabel.Text = 'BP EMG Channel Connections';
            app.BPEMGChannelConnectionsDropDown = uidropdown(app.OptionsTab);
            app.BPEMGChannelConnectionsDropDown.Items = {'Normal', 'Reversed'};
            app.BPEMGChannelConnectionsDropDown.ValueChangedFcn = createCallbackFcn(app, @BPEMGChannelConnectionsDropDownValueChanged, true);
            app.BPEMGChannelConnectionsDropDown.Tooltip = {'Normal is Logic for EMG1 and Proportional EMG2'};
            app.BPEMGChannelConnectionsDropDown.Value = 'Normal';
            app.N71F5777Label = uilabel(app.OptionsTab);
            app.N71F5777Label.Text = 'N7:1F57.77';
            app.Label_7 = uilabel(app.OptionsTab);
            app.Label_7.Text = '______________________________________';
            app.StandTab = uitab(app.ParGroup);
            app.StandTab.Title = 'Stand';
            app.Label = uilabel(app.StandTab);
            app.Label.Text = {'When this tab is open the gain'; ' and offset line in the WFL graph will reflect '; 'the EMG  used for the standing transter'};
            app.ScalePanel_2 = uipanel(app.StandTab);
            app.ScalePanel_2.Title = 'Scale';
            app.PoffsetLabel_2 = uilabel(app.ScalePanel_2);
            app.PoffsetLabel_2.Text = 'PoffsetDualASF';
            app.Poffset_2 = uieditfield(app.ScalePanel_2, 'numeric');
            app.Poffset_2.Tooltip = {'1F57.3, offset for dual ASF'};
            app.PgainLabel_2 = uilabel(app.ScalePanel_2);
            app.PgainLabel_2.Text = 'PgainDualASF';
            app.Pgain_2 = uieditfield(app.ScalePanel_2, 'numeric');
            app.Pgain_2.Tooltip = {'1F57.4, gain*10'};
            app.Pgain_2.Value = 1;
            app.UpdateButton_9 = uibutton(app.ScalePanel_2, 'push');
            app.UpdateButton_9.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_9Pushed, true);
            app.UpdateButton_9.Tooltip = {'Send proportional gain parameters for standing transfer'};
            app.UpdateButton_9.Text = '9';
            app.Label_2 = uilabel(app.StandTab);
            app.Label_2.Text = {'The command graph will present the trunk'; 'command rather than hand'};
            app.Label_3 = uilabel(app.StandTab);
            app.Label_3.Text = {'Remember to start the standing transfer script,'; 'but may want to set 0 for the transfer pattern'; ' while testing'; 'the command'};
            app.StartStandingTransferButton = uibutton(app.StandTab, 'push');
            app.StartStandingTransferButton.ButtonPushedFcn = createCallbackFcn(app, @StartStandingTransferButtonPushed, true);
            app.StartStandingTransferButton.Text = {'Start'; 'Standing'; 'Transfer'};
            app.Label_4 = uilabel(app.StandTab);
            app.Label_4.Text = {'Set action pattern (Trunk5) to zero if '; 'you want no stimulation'};
            app.Messages = uitextarea(app.UIFigure);
            app.ClearMessageButton = uibutton(app.UIFigure, 'push');
            app.ClearMessageButton.ButtonPushedFcn = createCallbackFcn(app, @ClearMessageButtonPushed, true);
            app.ClearMessageButton.Text = 'clear';
            app.ParamFileEditFieldLabel = uilabel(app.UIFigure);
            app.ParamFileEditFieldLabel.Text = 'Param File';
            app.ParamFileEditField = uieditfield(app.UIFigure, 'text');
            app.ViewEMGButton = uibutton(app.UIFigure, 'state');
            app.ViewEMGButton.ValueChangedFcn = createCallbackFcn(app, @ViewEMGButtonValueChanged, true);
            app.ViewEMGButton.Text = 'View EMG';
            app.NetLabel = uilabel(app.UIFigure);
            app.NetLabel.Enable = 'off';
            app.NetLabel.Text = 'Net';
            app.netLED = uilamp(app.UIFigure);
            app.netLED.Enable = 'off';
            app.PtLabel = uilabel(app.UIFigure);
            app.PtLabel.Enable = 'off';
            app.PtLabel.Text = 'Pt';
            app.ptLED = uilamp(app.UIFigure);
            app.ptLED.Enable = 'off';
            app.StatusPanel = uipanel(app.UIFigure);
            app.StatusPanel.Title = 'Status';
            app.LockLabel = uilabel(app.StatusPanel);
            app.LockLabel.Text = 'Lock';
            app.Lock = uilamp(app.StatusPanel);
            app.PatternLabel = uilabel(app.StatusPanel);
            app.PatternLabel.Text = 'Pattern';
            app.Pattern = uieditfield(app.StatusPanel, 'numeric');
            app.ToggleButton = uibutton(app.StatusPanel, 'push');
            app.ToggleButton.ButtonPushedFcn = createCallbackFcn(app, @ToggleButtonPushed, true);
            app.ToggleButton.Text = 'Toggle';
            app.XButton = uibutton(app.UIFigure, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Tooltip = {'Force recording to stop'};
            app.XButton.Text = 'X';
            app.Label_5 = uilabel(app.UIFigure);
            app.ChannelALabel = uilabel(app.UIFigure);
            app.ChannelALabel.Tooltip = {'Normally BP ch 1 unless inputs are swapped'};
            app.ChannelALabel.Text = 'Channel A';
            app.ChannelBLabel = uilabel(app.UIFigure);
            app.ChannelBLabel.Tooltip = {'Normally BP ch 2unless inputs are swapped'};
            app.ChannelBLabel.Text = 'Channel B';
            app.chEditFieldLabel = uilabel(app.UIFigure);
            app.chEditFieldLabel.Text = 'ch';
            app.chEditFieldA = uieditfield(app.UIFigure, 'numeric');
            app.chEditFieldA.Editable = 'off';
            app.chEditFieldA.Value = 1;
            app.chEditField_2Label = uilabel(app.UIFigure);
            app.chEditField_2Label.Text = 'ch';
            app.chEditFieldB = uieditfield(app.UIFigure, 'numeric');
            app.chEditFieldB.Editable = 'off';
            app.chEditFieldB.Value = 2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = emgRecordProcessedTab(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end