classdef fileNamesDialogBox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UserIDEditField           matlab.ui.control.EditField
        Label_Main                matlab.ui.control.Label
        CancelButton              matlab.ui.control.Button
        UpdateButton              matlab.ui.control.Button
        NewCommentFileButton      matlab.ui.control.Button
        CommentFileNameEditField  matlab.ui.control.EditField
        Label                     matlab.ui.control.Label
        NewLogFileButton          matlab.ui.control.Button
        FolderEditField           matlab.ui.control.EditField
        FolderButton              matlab.ui.control.Button
        LogFileNameEditField      matlab.ui.control.EditField
    end

    
    properties (Access = private)
        CallingApp % main app object
        
        selpath=pwd; % Description
    end
    
    properties (Access = public)
        LogFileName='LogFileNamedefault.csv'; % default file name
        CommentFileName='CommentFileNamedefault.txt'; % default file name
        prefix='';
    end
    
    methods (Access = private)
        
        function NewLogFileName(app)
            userstring=app.UserIDEditField.Value;
            timestring=sprintf('_%02.0f',datevec(now));
            %
            app.LogFileNameEditField.Value=[userstring,app.prefix,'Log',timestring,'.csv'];
            %
            app.LogFileName=fullfile(app.FolderEditField.Value,app.LogFileNameEditField.Value);            
        end
        
        function NewCommentFileName(app)
            userstring=app.UserIDEditField.Value;
            timestring=sprintf('_%02.0f',datevec(now));
            %
            app.CommentFileNameEditField.Value=[userstring,app.prefix,'Comments',timestring,'.txt'];
            %
            app.CommentFileName=fullfile(app.FolderEditField.Value,app.LogFileNameEditField.Value);     
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, user, folder, prefix)
           app.CallingApp = mainapp;
           app.UserIDEditField.Value=user;
           app.FolderEditField.Value=folder;
           app.selpath=folder;
           app.prefix=prefix;
           app.NewLogFileName();
           app.NewCommentFileName();
        end

        % Button pushed function: FolderButton
        function FolderButtonPushed(app, event)
         newpath = uigetdir(app.selpath,'Folder to store Log files');
            if ischar(newpath) % if canceled, this is a number 0
                if isfolder(newpath)  % can only use this with character array
                    app.selpath=newpath;  % update the path if a new one given
                    app.FolderEditField.Value=app.selpath;
                    app.FolderButton.BackgroundColor=[0.96,0.96,0.96]; % from [1,.9,.6]
                    % Create or open a comment file to add comments
                    % Create the file name but don't open until the comment
                    % is added, then append.
                    app.NewCommentFileName(); 
                end
            end
            figure(app.UIFigure); % Restore focus to this window
            %   
        end

        % Button pushed function: NewLogFileButton
        function NewLogFileButtonButtonPushed(app, event)
            app.NewLogFileName(); 
        end

        % Button pushed function: NewCommentFileButton
        function NewCommentFileButtonButtonPushed(app, event)
            app.NewCommentFileName(); 
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
         %here I could have used passed the full variables instead   
         NewFileNames(app.CallingApp,app.UserIDEditField.Value,app.FolderEditField.Value,app.LogFileName,app.CommentFileName);         
         % Delete the dialog box
         delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
          % If I need to change anything in the main app, do it here
          
          % close the dialog box
          delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'GetFileNameDialogBox';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LogFileNameEditField = uieditfield(app.UIFigure, 'text');
            app.LogFileNameEditField.Editable = 'off';
            app.FolderButton = uibutton(app.UIFigure, 'push');
            app.FolderButton.ButtonPushedFcn = createCallbackFcn(app, @FolderButtonPushed, true);
            app.FolderButton.Text = 'Folder';
            app.FolderEditField = uieditfield(app.UIFigure, 'text');
            app.FolderEditField.Editable = 'off';
            app.NewLogFileButton = uibutton(app.UIFigure, 'push');
            app.NewLogFileButton.ButtonPushedFcn = createCallbackFcn(app, @NewLogFileButtonButtonPushed, true);
            app.NewLogFileButton.Text = 'New Log File';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'User ID';
            app.CommentFileNameEditField = uieditfield(app.UIFigure, 'text');
            app.CommentFileNameEditField.Editable = 'off';
            app.NewCommentFileButton = uibutton(app.UIFigure, 'push');
            app.NewCommentFileButton.ButtonPushedFcn = createCallbackFcn(app, @NewCommentFileButtonButtonPushed, true);
            app.NewCommentFileButton.Text = 'New Comment File';
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Label_Main = uilabel(app.UIFigure);
            app.Label_Main.Text = 'Change and Update Log and Comment File Names';
            app.UserIDEditField = uieditfield(app.UIFigure, 'text');
            app.UserIDEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fileNamesDialogBox(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end