classdef groupedit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PatternGroupEditorFigure  matlab.ui.Figure
        CreatefromTemplateButton  matlab.ui.control.Button
        DuplicateGroupButton      matlab.ui.control.Button
        RevertButton              matlab.ui.control.Button
        ApplyButton               matlab.ui.control.Button
        RemoveGroupButton         matlab.ui.control.Button
        AddGroupButton            matlab.ui.control.Button
        UITable                   matlab.ui.control.Table
    end

    
    properties (Access = public)
        groups = []; % Description
        currentRow = [];
        parentApp = [];
        removePatternsOnApply = [];
        movePatternsOnApply = [];
        changeCmdIDOnApply = [];
        duplicatePatternsOnApply = [];
        createdPatterns=[]; % update in patternAutoCreate.mlapp
        addCreatedPatterns=false; % set flag true if createdPatterns not blank, and Apply
        newName=[];
        newcmdID=[];
       
    end
    
    properties (Access = private)

    end
    
    methods (Access = private)
        
        function populateTable(app)
            k=1;
            d = [];
            for i=1:length(app.groups)
                if ~isempty(app.groups{i})
                    d{k,1} = i;
                    d{k,2} = app.groups{i}.cmdID;
                    d{k,3} = app.groups{i}.name;
                    d{k,4} = app.groups{i}.download;
                    d{k,5} = app.groups{i}.notes;
                    k=k+1;
                end
            end
            app.UITable.Data = d;
        end
        
        function trimGroups(app, group)
            app.groups{group} =[];
            
            % if last group, remove it, not just make it empty
            while group == length( app.groups)
                if isempty(app.groups{group})
                    app.groups(group) = [];
                    group = group - 1;
                    if group == 0
                        break;
                    end
                else 
                    break;
                end
            end
        end
        
        function enableApplyRevert(app, enable)
            app.ApplyButton.Enable = enable;
            app.RevertButton.Enable = enable;
        end
        
        function newGroup = findFirstUnusedGroup(app)
            %find first unused group
            if isempty(app.UITable.Data)
                newGroup = 1;
            else
                activeGroups = cell2mat(app.UITable.Data(:, 1));
                if ismember(1, activeGroups)
                    i = find(diff(activeGroups)>1, 1);
                    if isempty(i)
                        newGroup = activeGroups(end)+1;
                    else
                        newGroup = activeGroups(i)+1;
                    end
                else
                    newGroup = 1;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, PARENTAPP)
            app.parentApp = PARENTAPP;
            app.groups = app.parentApp.nnps.groups;
            app.populateTable();
            
            app.UITable.ColumnFormat = {'char', 'char'}; %left align numbers in frist two columns
            app.enableApplyRevert(false);
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.currentRow = event.Indices(1);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            row = event.Indices(1);    
            col = event.Indices(2);
            
            group = app.UITable.Data{row, 1};
            
            newData = event.NewData;
            if col ==1 || col == 2
                newData = event.NewData;
                if ischar(newData)
                    newData = str2double(newData);
                end
            end
            switch col
                case 1
                    oldGroup = event.PreviousData;
                    newGroup = newData;
                    activeGroups = cell2mat(app.UITable.Data(:, col));
                    activeGroups(row) = [];
                    if isnan(newGroup) || newGroup>255 || newData<1 || ismember(newGroup, activeGroups)
                           app.UITable.Data{row, col} = oldGroup;
                           warning('group out of range')
                           return
                    end
                    if app.parentApp.groupInUse(oldGroup)
                        if app.parentApp.groupInUse(newGroup)
                            warning('new group in use already')
                            app.UITable.Data{row, col} = oldGroup;
                            return;
                        end
                        if (~isempty(app.duplicatePatternsOnApply) && (ismember(newGroup, app.duplicatePatternsOnApply) || ismember(oldGroup, app.duplicatePatternsOnApply))) ||...
                           (~isempty(app.movePatternsOnApply) && (ismember(newGroup, app.movePatternsOnApply) || ismember(oldGroup, app.movePatternsOnApply))) ||...
                           (~isempty(app.removePatternsOnApply) && (ismember(newGroup, app.removePatternsOnApply) || ismember(oldGroup, app.removePatternsOnApply)))
                           msgbox('Patterns involved have already been moved/removed/or duplicated.  Please apply/revert past changes first and try again','Group Editor');
                           return;
                        end
                        resp = questdlg({['Group #' num2str(oldGroup) ' has patterns associated with it.  If moved, the assoicated patterns will also be moved when applying changes.'];'';...
                                    ['Are you sure you want to move all patterns associated with group #' num2str(oldGroup) ' to group #' num2str(newGroup) '?']}, 'Group Editor');
                    
                        %No/Cancel will not move group or patterns associated with group
                        %Yes changes group number and moves associated patterns 
                        if isequal(resp, 'Yes')
                            app.movePatternsOnApply = [app.movePatternsOnApply; [oldGroup newGroup]]; 
                        else
                            app.UITable.Data{row, col} = oldGroup;
                            return
                        end
                    end
                    app.groups{newGroup} = app.groups{oldGroup};
                    app.trimGroups(oldGroup);             
                    app.populateTable();

                case 2
                    if isnan(newData) || newData>8 || newData<1
                       app.UITable.Data{row, col} = event.PreviousData;
                       warning('cmdID out of range')
                       return;
                    else
                        if app.parentApp.groupInUse(group)
                            resp = questdlg({'This group has patterns associated with it.  When applying changes, all patterns associated with this group will also change cmdID.';'';...
                                ['Do you want to continue changing cmdID for' num2str(group) '?']}, 'Group Editor');
                            %No/Cancel will not change cmdID for patterns associated with group
                            %Yes changes group cmdID and associated patterns 
                            if isequal(resp, 'Yes') 
                                app.groups{group}.cmdID = newData;
                                app.changeCmdIDOnApply = [app.changeCmdIDOnApply; [group newData]];
                            else
                                app.UITable.Data{row, col} = event.PreviousData;
                                return;
                            end
                        end
                    end
                case 3
                    app.groups{group}.name = newData;
                case 4
                    app.groups{group}.download = newData;
                case 5
                    app.groups{group}.notes = newData;
            end
            % all changes made above should enable the Apply/Revert buttons.
            % any discard changes should have returned before getting here
            app.enableApplyRevert(true);
            
        end

        % Button pushed function: AddGroupButton
        function AddGroupButtonPushed(app, event)

            newGroup = app.findFirstUnusedGroup();
            
            resp = inputdlg({sprintf('New Group#%2.0f\n\nSpecify the command index to use with this group\ncmdID (1-8):', newGroup), 'Name (optional):'}, 'Group Editor');
            if length(resp)<1
                return;
            end
            cmdID = str2double(resp{1});
            if length(resp)>1
                name = resp{2};
            else 
                name = '?';
            end
            if length(cmdID)~=1 || isnan(cmdID) || cmdID<1 || cmdID>8
                 warning('invalid cmdID entry')
                 return;
             end
            
            %force a column cell array
            if length(app.groups)==1 && newGroup>1
                app.groups = [app.groups; {[]}];
            end 
            app.groups{newGroup} = struct('name', name, 'cmdID', cmdID,'download', true, 'notes', '');
            app.populateTable();
            app.enableApplyRevert(true);
        end

        % Button pushed function: RemoveGroupButton
        function RemoveGroupButtonPushed(app, event)
            if isempty(app.currentRow)  || app.currentRow > size(app.UITable.Data, 1)
                return;
            end
            group = app.UITable.Data{app.currentRow, 1};
            if (~isempty(app.duplicatePatternsOnApply) && ismember(group, app.duplicatePatternsOnApply)) ||...
               (~isempty(app.movePatternsOnApply) && ismember(group, app.movePatternsOnApply)) ||...
               (~isempty(app.removePatternsOnApply) && ismember(group, app.removePatternsOnApply))
               msgbox('Patterns involved have already been moved/removed/or duplicated.  Please apply/revert past changes first and try again','Group Editor');
               return;
            end
            
            if app.parentApp.groupInUse(group)
                
                resp = questdlg({'This group has patterns associated with it.  If deleted, the assoicated patterns will also be removed when applying changes.';'';...
                    ['Are you sure you want to delete all patterns associated with group #' num2str(group) '?']}, 'Group Editor');
                %No/Cancel will not delete group or patterns associated with group
                %Yes deletes group number and associated patterns 
                if isequal(resp, 'Yes') 
                    app.removePatternsOnApply = [app.removePatternsOnApply group];
                else
                    return;
                end
            end
            app.trimGroups(group);
            app.populateTable();
            app.enableApplyRevert(true);
        end

        % Close request function: PatternGroupEditorFigure
        function PatternGroupEditorFigureCloseRequest(app, event)
            if isvalid(app.parentApp)
                if ~isequal(app.parentApp.nnps.groups, app.groups)
                    resp = questdlg('Do you want to apply changes?', 'Group Editor');
                    if isequal(resp, 'Yes')
                        app.ApplyButtonPushed([]);
                    elseif ~isequal(resp, 'No')
                        return
                    end

                end
                % validity of partentApp has already been tested
                %  figure(app.parentApp.UIFigure);  % this works, if I know the figure name
                % Assume I don't know the name of the calling figure
                % I know the calling app is app.parentApp, so I can get the list of properties
                parentProp=properties(app.parentApp);
                % the first property is the name of the uifigure
                parentFigureName=parentProp{1};
                hParentFigure=eval(strcat('app.parentApp.',parentFigureName)); % handle to figure
                if isvalid(hParentFigure) % check that is is valid (has not been closed
                    figure(hParentFigure); % bring to front
                end
                % I could have condenced the last three lines (without validity checking) to:
                %  figure(eval(strcat('app.parentApp.',parentProp{1})));
            else
                % warning('parent app closed');
                disp('parent app closed');
            end
            
            delete(app)
                  
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            if isvalid(app.parentApp)     
                if any(cellfun(@(x) ~isempty(x) && ~ismember(x.cmdID, 1:8),app.groups))
                    warndlg('Cannot apply. At least one group does not have valid cmdID')
                end
                %should there be an else here so that the rest is skipped
                %if not valid??

                disp('applied groupeditor changes')
                app.parentApp.nnps.groups = app.groups;
                
                if app.addCreatedPatterns  % new patterns have been added in patternAutoCreate.mlapp
                    app.addCreatedPatterns=false;
                    app.parentApp.addNewPatterns(app.createdPatterns);
                    app.createdPatterns=[];
                end

                for group = app.removePatternsOnApply % this assumes 1xn array but the concatenate [] creates new rows with ;
                    app.parentApp.removePatternsGroup(group);
                end

                for i = 1:size(app.movePatternsOnApply, 1)
                    oldGroup = app.movePatternsOnApply(i,1);
                    newGroup = app.movePatternsOnApply(i,2);
                    app.parentApp.changePatternsGroup(oldGroup, newGroup)  
                end
                for i = 1:size(app.duplicatePatternsOnApply,1)
                    oldGroup = app.duplicatePatternsOnApply(i,1);
                    newGroup = app.duplicatePatternsOnApply(i,2);
                    app.parentApp.duplicatePatternsGroup(oldGroup, newGroup);  
                end
                for i = 1:size(app.changeCmdIDOnApply,1)
                    group = app.changeCmdIDOnApply(i,1);
                    cmdID = app.changeCmdIDOnApply(i,2);
                    app.parentApp.changePatternsCmdID(group, cmdID);  
                end
                app.removePatternsOnApply = [];
                app.movePatternsOnApply = [];
                app.parentApp.updateDropDowns; 
                app.parentApp.updateChannelName();
                app.enableApplyRevert(false);
            else
                warning('parent app closed')
            end    
        end

        % Button pushed function: RevertButton
        function RevertButtonPushed(app, event)
            if isvalid(app.parentApp)
                app.removePatternsOnApply = [];
                app.movePatternsOnApply = [];
                app.changeCmdIDOnApply = [];
                 app.duplicatePatternsOnApply = [];
                app.groups = app.parentApp.nnps.groups;
                app.populateTable();
                app.enableApplyRevert(false);
            else
                warning('parent app closed')
            end
        end

        % Button pushed function: DuplicateGroupButton
        function DuplicateGroupButtonPushed(app, event)
            if isempty(app.currentRow)  || app.currentRow > size(app.UITable.Data, 1)
                return;
            end
            oldGroup = app.UITable.Data{app.currentRow, 1};   
            newGroup = app.findFirstUnusedGroup();
            
            if (~isempty(app.duplicatePatternsOnApply) && (ismember(newGroup, app.duplicatePatternsOnApply) || ismember(oldGroup, app.duplicatePatternsOnApply))) ||...
               (~isempty(app.movePatternsOnApply) && (ismember(newGroup, app.movePatternsOnApply) || ismember(oldGroup, app.movePatternsOnApply))) ||...
               (~isempty(app.removePatternsOnApply) && (ismember(newGroup, app.removePatternsOnApply) || ismember(oldGroup, app.removePatternsOnApply)))
               msgbox('Patterns involved have already been moved/removed/or duplicated.  Please apply/revert past changes first and try again','Group Editor');
               return;
            end
            
            if ~app.parentApp.groupInUse(oldGroup)
                resp = questdlg({'This group has no patterns associated with it yet.';'';...
                    ['Are you sure you want to duplicate group #' num2str(oldGroup) ' into group #' num2str(newGroup) '?']}, 'Group Editor');
                %No/Cancel will not duplicate group or patterns associated with group
                if ~isequal(resp, 'Yes') 
                    return;
                end
            end
            
            %force a column cell array
            if length(app.groups)==1 && newGroup>1
                app.groups = [app.groups; {[]}];
            end 
            
            app.groups{newGroup} = app.groups{oldGroup};
            resp = inputdlg(['Specify name for new group#' num2str(newGroup) ':']);
            if length(resp)==1
                app.groups{newGroup}.name = resp{1};
            else
                app.groups{newGroup}.name = [app.groups{newGroup}.name ' (copy)' ];
            end
            app.duplicatePatternsOnApply = [app.duplicatePatternsOnApply; [oldGroup, newGroup]];
            app.populateTable();
            app.enableApplyRevert(true);
        end

        % Button pushed function: CreatefromTemplateButton
        function CreatefromTemplateButtonPushed(app, event)
           newGroup = app.findFirstUnusedGroup();
            
            resp = inputdlg({sprintf('New Group#%2.0f\n\nSpecify the command index to use with this group\ncmdID (1-8):', newGroup), 'Name (optional):'}, 'Group Editor');
            if length(resp)<1
                return;
            end
            cmdID = str2double(resp{1});
            if length(resp)>1
                name = resp{2};
            else 
                name = '?';
            end
            if length(cmdID)~=1 || isnan(cmdID) || cmdID<1 || cmdID>8
                 warning('invalid cmdID entry')
                 return;
             end
            
            %force a column cell array
            if length(app.groups)==1 && newGroup>1
                app.groups = [app.groups; {[]}];
            end 
            % insert the application here to create the template patterns
            templateStructure=load('PatternTemplateConstants.mat');
            if isempty(app.parentApp.profileName)
                    [filename, filepath] = uigetfile('.mat','Select Profile file');
                    if filename
                         app.parentApp.profileName=[filepath filename];
                    end
                    figure(app.PatternGroupEditorFigure); %return focus after nnp opened if needed  
            end
            ld=load(app.parentApp.profileName,'data');
            profileTable=ld.data;
            app.newName=name; %set local variables to allow changes within called app
            app.newcmdID=cmdID;
            patternAutoCreate(app,templateStructure, profileTable, newGroup, cmdID, name);
            
            name=app.newName;  % added this extra step to allow me to simply comment these lines out
            cmdID=app.newcmdID; % added this extra step to allow me to simply comment these lines out
            app.groups{newGroup} = struct('name', name, 'cmdID', cmdID,'download', true, 'notes', '');
            app.populateTable();
            app.enableApplyRevert(true);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PatternGroupEditorFigure = uifigure('Visible', 'off');
            app.PatternGroupEditorFigure.Name = 'Pattern Group Editor';
            app.PatternGroupEditorFigure.CloseRequestFcn = createCallbackFcn(app, @PatternGroupEditorFigureCloseRequest, true);
            app.UITable = uitable(app.PatternGroupEditorFigure);
            app.UITable.ColumnName = {'Group'; 'Cmd ID'; 'Name'; 'Download'; 'Notes'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true true true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.AddGroupButton = uibutton(app.PatternGroupEditorFigure, 'push');
            app.AddGroupButton.ButtonPushedFcn = createCallbackFcn(app, @AddGroupButtonPushed, true);
            app.AddGroupButton.Text = '+';
            app.RemoveGroupButton = uibutton(app.PatternGroupEditorFigure, 'push');
            app.RemoveGroupButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveGroupButtonPushed, true);
            app.RemoveGroupButton.Text = '-';
            app.ApplyButton = uibutton(app.PatternGroupEditorFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.RevertButton = uibutton(app.PatternGroupEditorFigure, 'push');
            app.RevertButton.ButtonPushedFcn = createCallbackFcn(app, @RevertButtonPushed, true);
            app.RevertButton.Text = 'Revert';
            app.DuplicateGroupButton = uibutton(app.PatternGroupEditorFigure, 'push');
            app.DuplicateGroupButton.ButtonPushedFcn = createCallbackFcn(app, @DuplicateGroupButtonPushed, true);
            app.DuplicateGroupButton.Text = 'DUPLICATE';
            app.CreatefromTemplateButton = uibutton(app.PatternGroupEditorFigure, 'push');
            app.CreatefromTemplateButton.ButtonPushedFcn = createCallbackFcn(app, @CreatefromTemplateButtonPushed, true);
            app.CreatefromTemplateButton.Text = 'Create from Template';
            % Show the figure after all components are created
            app.PatternGroupEditorFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = groupedit(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PatternGroupEditorFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PatternGroupEditorFigure)
        end
    end
end