classdef logicalPatternMap < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LogicalPatternMapUIFigure     matlab.ui.Figure
        OpenMenu                      matlab.ui.container.Menu
        LoadgroupsfromMATfileMenu     matlab.ui.container.Menu
        LoadgroupsfromNNPSfileMenu    matlab.ui.container.Menu
        LoadLogicalMapandgroupsMenu   matlab.ui.container.Menu
        SaveMenu                      matlab.ui.container.Menu
        SavelogicalMapwithgroupsMenu  matlab.ui.container.Menu
        FunctionalGroupsinfileordownloadedLabel  matlab.ui.control.Label
        LogicalPatterntoFunctionalGroupMappingLabel  matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        ClearMessageButton            matlab.ui.control.Button
        MessagesLabel                 matlab.ui.control.Label
        Messages                      matlab.ui.control.TextArea
        WritePMButton                 matlab.ui.control.Button
        ReadPMButton                  matlab.ui.control.Button
        FunctionalGroupTable          matlab.ui.control.Table
        LogicalMappingTable           matlab.ui.control.Table
    end

    
    properties (Access = public)
        LogicalPatternTable=[]; % Description
        bgColors=ones(32,3); % start with all zeros
        logicalMap=zeros(32,1); % Description
        nnp=[];
        nnps=[]; % structure for groups, channels, nodes, patterns
        saveFile=[]; % Description
        logicalMapNames=cell(32,1); % Names associated with assigned functional groups
        ltYellow=[1,1,0.8];
        styleYellowBold=uistyle('BackgroundColor',[1,1,.8],'FontWeight','bold') % light yellow bold
        callingFigure=[]; %handle to calling figure
    end
    
    methods (Access = public)
        
        function setBgColors(app)
            % set the colors of the table
            % rather than striping every other row, I want to group them.
            % Start with just two colors
            white=[1,1,1];
            ltBlue=[.8,1,1];
            % since they started all ones, only need to set every other group
            for i=[6:8,12:16,20:22,28:32]
                app.bgColors(i,:)=ltBlue;
            end
            app.LogicalMappingTable.BackgroundColor=app.bgColors;
        end
        
               
        function ClearMessages(app)                   
           app.Messages.Value=''; % clear messages 
        end
                
        function TopMessage(app,message)
          app.Messages.Value=vertcat(message, string(app.Messages.Value)); % put new message on top  
        end
        
        
        function updateFGTables(app)
%            	groups	18x1 cell
%           		{'name'    }
%           		{'cmdID'   }
%           		{'download'}
%           		{'notes'   }
% What if the FG numbers are not contiguous?
            for i=1:size(app.nnps.groups,1)
                gpnum(i)=i;
                cmdID(i)=app.nnps.groups{i}.cmdID;
                name{i}=app.nnps.groups{i}.name;
                download(i)=app.nnps.groups{i}.download;
                notes{i}=app.nnps.groups{i}.notes;
                gpt=table(gpnum',cmdID',name',download',notes');
                app.FunctionalGroupTable.Data=gpt;                
            end
        end
        
        function fillLogTable(app)
          for i=1:32
              fg=app.logicalMap(i);
              if fg>0
                  fgname=app.nnps.groups{fg}.name;
                  if ~isempty(fgname)
                      app.logicalMapNames{i}=fgname;
                  else
                      app.logicalMapNames{i}='none';
                  end
              else
                  app.logicalMapNames{i}='none';
              end
          end
          app.LogicalMappingTable.Data{:,4}=app.logicalMap;
          app.LogicalMappingTable.Data{:,5}=app.logicalMapNames;
        end
        
        function defaultLogicalMap(app)
            %simplify this by adding columns
            % cmdID, Logical, #, FG, Name
            cmdID=[1,1,1,1,1,2,2,2,3,3,3,4,4,4,4,4,5,5,5,6,6,6,7,7,7,7,7,8,8,8,8,8]';
            Logical={'Hand','','','','',...
                'Aux1','','',...
                'Aux2','','',...
                'Trunk','','','','',...
                'Aux3','','',...
                'Aux4','','',...
                'Exer1','','','','',...
                'Exer2','','','',''}';
            LG=[1:5,1:3,1:3,1:5,1:3,1:3,1:5,1:5]';
            FG=zeros(32,1);
            Names=cell(32,1);
            for i=1:32
                Names{i}='none';
            end           
            app.LogicalPatternTable=table(cmdID,Logical,LG,FG,Names);
            % app.LogicalMappingTable.ColumnFormat={'numeric','numeric'}; %to right justify
            app.LogicalMappingTable.Data=app.LogicalPatternTable;
            app.setBgColors();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin<2
                NNP=[];
            else
                NNP=varargin{1};
            end
            if isempty(NNP)
                app.nnp = NNPAPI();
                if isempty(app.nnp.port)
                    msgbox('No port chosen for NNP Access Point')
                    delete(app.nnp)
                    return
                end
            else
                app.nnp = NNP;
            end
            if nargin>2
                app.callingFigure=varargin{2};
            end

            app.defaultLogicalMap();
            addStyle(app.LogicalMappingTable,app.styleYellowBold,'column',4); % first added so this will be 1 if I want to remove
        end

        % Menu selected function: LoadgroupsfromMATfileMenu
        function LoadgroupsfromMATfileMenuSelected(app, event)
%             if  ~isempty(app.Patterns) || ~isempty(app.nnps) && (~isempty(app.nnps.channels) || ~isempty(app.nnps.nodes) || ~isempty(app.nnps.groups))
%                 resp = questdlg('You may have unsaved changes.  Do you want to continue with a new file?');
%                 if ~isequal(resp, 'Yes')
%                     return;
%                 end
%             end
            
            [filename, filepath] = uigetfile('.mat', 'Select NNP System File');
            if filename
                ld = load([filepath filename]);
                TempFile = [filepath filename];  % save filename as temporary until the file is checked

            else
                return;
            end
            % I could skip this for patterns
            fields = { 'channels', 'nodes', 'groups'};
            iMissing = find(~isfield(ld, fields));
            
            str = [];
            for i=1:length(iMissing)
                str = [str ', ' fields{iMissing(i)}];
            end
            if ~isempty(str)
                warndlg(['Invalid NNP System File.  Does not contain variable(s): ' str '.'], 'Open File')
                return;
           end     
           app.nnps.nodes = ld.nodes;
           app.nnps.groups = ld.groups;
           app.updateFGTables();
           app.ReadPMButton.Enable='on';
           app.WritePMButton.Enable='on';
            
        end

        % Menu selected function: LoadgroupsfromNNPSfileMenu
        function LoadgroupsfromNNPSfileMenuSelected(app, event)
            dmessage='loading from file';
            app.TopMessage(dmessage);
            [success, newchannels, newnodes, newgroups]=readnnpsCNG();
            if success==1
                app.nnps.channels=newchannels;
                app.nnps.nodes=newnodes;
                app.nnps.groups=newgroups;
                app.TopMessage('success');
                app.updateFGTables(); 
                app.ReadPMButton.Enable='on';
                app.WritePMButton.Enable='on';
            else
                 app.TopMessage('fail');
            end
        end

        % Button pushed function: ReadPMButton
        function ReadPMButtonPushed(app, event)
             new57=app.logicalMap; % initialize with starting data
            % Read in three reads
            resp=app.nnp.read(7,'1F57',17,32,'uint8');
            if(length(resp)==32) %valid packet
                new57(1:32)=double(resp);
                app.logicalMap=new57;
                dmessage='Read PM 1F57';
            else
                dmessage='error reading PM';
                return;
            end
            app.TopMessage(dmessage);
            % fill in the blanks
               app.fillLogTable();
        end

        % Button pushed function: WritePMButton
        function WritePMButtonPushed(app, event)
            result = app.nnp.write(7, '1F57', 17, uint8(app.LogicalMappingTable.Data{:,4}'), 'uint8',32);
        if ~isequal(result, 0)
            app.TopMessage('Error Writing Logical Patterns to PM')
        end
        app.TopMessage('Logical Patterns to PM...');
        
        end

        % Cell selection callback: LogicalMappingTable
        function LogicalMappingTableCellSelection(app, event)
            indices = event.Indices;
            row=indices(1);
            % read cmdID listed in LMT row
            LcmdID=app.LogicalMappingTable.Data{row,1};
            fgRows=size(app.FunctionalGroupTable.Data,1);
            % for all rows, if cmdID==cmdID, make row white
            if LcmdID>6
            bgColor=ones(fgRows,3); %start with all white
            else
             bgColor=ones(fgRows,3)*.90; %start with all grey               
            end
            for i=1:fgRows
                if app.FunctionalGroupTable.Data{i,2}==LcmdID
                    bgColor(i,:)=app.ltYellow;
                end
            end
            app.FunctionalGroupTable.BackgroundColor=bgColor;
        end

        % Cell edit callback: LogicalMappingTable
        function LogicalMappingTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            % 
            newName=app.FunctionalGroupTable.Data{newData,3};
            app.LogicalMappingTable.Data{indices(1),5}=newName;
        end

        % Close request function: LogicalPatternMapUIFigure
        function LogicalPatternMapUIFigureCloseRequest(app, event)
            if ishandle(app.callingFigure) % check that is is valid
                figure(app.callingFigure); % bring to front
            end
            delete(app)
 
        end

        % Menu selected function: LoadLogicalMapandgroupsMenu
        function LoadLogicalMapandgroupsMenuSelected(app, event)
            % load logicalMap groups variables from mat file
            [filename, filepath] = uigetfile('.mat', 'Select file with logical map and groups');
            if filename
                ld = load([filepath filename]);
                TempFile = [filepath filename];  % save filename as temporary until the file is checked

            else
                return;
            end
            % I could skip this for patterns
            fields = {'logicalMap', 'groups'};
            iMissing = find(~isfield(ld, fields));
            
            str = [];
            for i=1:length(iMissing)
                str = [str ', ' fields{iMissing(i)}];
            end
            if ~isempty(str)
                warndlg(['Invalid Logical Map File.  Does not contain variable(s): ' str '.'], 'Open File')
                return;
           end     
           %  If the file is invalid, we don't want to update anything, including the file name
           app.saveFile = TempFile;   % the only file we need to save is logicalMap file
           app.nnps.groups = ld.groups;
           app.logicalMap=ld.logicalMap;
           app.fillLogTable();
           app.updateFGTables();
           app.ReadPMButton.Enable='on';
           app.WritePMButton.Enable='on';
        end

        % Menu selected function: SavelogicalMapwithgroupsMenu
        function SavelogicalMapwithgroupsMenuSelected(app, event)
            % Save logicalMap and groups to file
            % could add separate SaveAs and Save callbacks
            [filename, filepath] = uiputfile('.mat', 'Select file to save logical map and groups',app.saveFile);
            if filename
                saveFile=[filepath filename];
                groups=app.nnps.groups;
                logicalMap=app.logicalMap;
                save(saveFile,'groups','logicalMap'); % save as variables.  Use opened file as default for saving
                app.TopMessage('groups and logicalMap saved to file');
            else
                return;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LogicalPatternMapUIFigure = uifigure('Visible', 'off');
            app.LogicalPatternMapUIFigure.Name = 'Logical Pattern Map';
            app.LogicalPatternMapUIFigure.CloseRequestFcn = createCallbackFcn(app, @LogicalPatternMapUIFigureCloseRequest, true);
            app.OpenMenu = uimenu(app.LogicalPatternMapUIFigure);
            app.OpenMenu.Text = 'Open';
            app.LoadgroupsfromMATfileMenu = uimenu(app.OpenMenu);
            app.LoadgroupsfromMATfileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadgroupsfromMATfileMenuSelected, true);
            app.LoadgroupsfromMATfileMenu.Tooltip = {'System file, loads channels, nodes and groups but this window only uses groups'};
            app.LoadgroupsfromMATfileMenu.Text = 'Load groups from MAT file';
            app.LoadgroupsfromNNPSfileMenu = uimenu(app.OpenMenu);
            app.LoadgroupsfromNNPSfileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadgroupsfromNNPSfileMenuSelected, true);
            app.LoadgroupsfromNNPSfileMenu.Tooltip = {'Loads channels, nodes and groups but this window only uses groups'};
            app.LoadgroupsfromNNPSfileMenu.Text = 'Load groups from NNPS file';
            app.LoadLogicalMapandgroupsMenu = uimenu(app.OpenMenu);
            app.LoadLogicalMapandgroupsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadLogicalMapandgroupsMenuSelected, true);
            app.LoadLogicalMapandgroupsMenu.Text = 'Load Logical Map and groups';
            app.SaveMenu = uimenu(app.LogicalPatternMapUIFigure);
            app.SaveMenu.Text = 'Save';
            app.SavelogicalMapwithgroupsMenu = uimenu(app.SaveMenu);
            app.SavelogicalMapwithgroupsMenu.MenuSelectedFcn = createCallbackFcn(app, @SavelogicalMapwithgroupsMenuSelected, true);
            app.SavelogicalMapwithgroupsMenu.Text = 'Save logicalMap with groups';
            app.LogicalMappingTable = uitable(app.LogicalPatternMapUIFigure);
            app.LogicalMappingTable.ColumnName = {'cmdID'; 'Logical'; '#'; 'FG'; 'Name'};
            app.LogicalMappingTable.RowName = {};
            app.LogicalMappingTable.ColumnEditable = [false false false true false];
            app.LogicalMappingTable.CellEditCallback = createCallbackFcn(app, @LogicalMappingTableCellEdit, true);
            app.LogicalMappingTable.CellSelectionCallback = createCallbackFcn(app, @LogicalMappingTableCellSelection, true);
            app.FunctionalGroupTable = uitable(app.LogicalPatternMapUIFigure);
            app.FunctionalGroupTable.ColumnName = {'Group'; 'Cmd ID'; 'Name'; 'Download'; 'Notes'};
            app.FunctionalGroupTable.RowName = {};
            app.FunctionalGroupTable.ColumnSortable = [true true false false false];
            app.FunctionalGroupTable.ColumnEditable = [true true true true true];
            app.Panel = uipanel(app.LogicalPatternMapUIFigure);
            app.Panel.Title = 'Panel';
            app.ReadPMButton = uibutton(app.Panel, 'push');
            app.ReadPMButton.ButtonPushedFcn = createCallbackFcn(app, @ReadPMButtonPushed, true);
            app.ReadPMButton.Enable = 'off';
            app.ReadPMButton.Text = 'ReadPM';
            app.WritePMButton = uibutton(app.Panel, 'push');
            app.WritePMButton.ButtonPushedFcn = createCallbackFcn(app, @WritePMButtonPushed, true);
            app.WritePMButton.Enable = 'off';
            app.WritePMButton.Text = 'WritePM';
            app.Messages = uitextarea(app.Panel);
            app.MessagesLabel = uilabel(app.Panel);
            app.MessagesLabel.Text = 'Messages';
            app.ClearMessageButton = uibutton(app.Panel, 'push');
            app.ClearMessageButton.Text = 'clear';
            app.LogicalPatterntoFunctionalGroupMappingLabel = uilabel(app.LogicalPatternMapUIFigure);
            app.LogicalPatterntoFunctionalGroupMappingLabel.Text = 'Logical Pattern to Functional Group Mapping';
            app.FunctionalGroupsinfileordownloadedLabel = uilabel(app.LogicalPatternMapUIFigure);
            app.FunctionalGroupsinfileordownloadedLabel.Text = 'Functional Groups in file or downloaded';
            % Show the figure after all components are created
            app.LogicalPatternMapUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = logicalPatternMap(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LogicalPatternMapUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LogicalPatternMapUIFigure)
        end
    end
end