classdef mesTestData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MESTestDataUIFigure        matlab.ui.Figure
        MessagesLabel              matlab.ui.control.Label
        ClearMessageButton         matlab.ui.control.Button
        Messages                   matlab.ui.control.TextArea
        TestDataButton             matlab.ui.control.Button
        BPMESButton                matlab.ui.control.Button
        MES2Panel                  matlab.ui.container.Panel
        TwitchLabel_2              matlab.ui.control.Label
        RampLabel_2                matlab.ui.control.Label
        StepLabel_2                matlab.ui.control.Label
        mtypesentEditField_2       matlab.ui.control.NumericEditField
        mtypesentEditField_2Label  matlab.ui.control.Label
        MESButton11_2              matlab.ui.control.Button
        MESButton10_2              matlab.ui.control.Button
        MESButton9_2               matlab.ui.control.Button
        MESButton8_2               matlab.ui.control.Button
        MESButton7_2               matlab.ui.control.Button
        MESButton6_2               matlab.ui.control.Button
        MESButton5_2               matlab.ui.control.Button
        MESButton4_2               matlab.ui.control.Button
        MESButton3_2               matlab.ui.control.Button
        MESButton2_2               matlab.ui.control.Button
        MESButton1_2               matlab.ui.control.Button
        MESButton0_2               matlab.ui.control.Button
        HoldLabel_2                matlab.ui.control.Label
        MES1Panel                  matlab.ui.container.Panel
        TwitchLabel                matlab.ui.control.Label
        RampLabel                  matlab.ui.control.Label
        StepLabel                  matlab.ui.control.Label
        mtypesentEditField         matlab.ui.control.NumericEditField
        mtypesentEditFieldLabel    matlab.ui.control.Label
        MESButton11                matlab.ui.control.Button
        MESButton10                matlab.ui.control.Button
        MESButton9                 matlab.ui.control.Button
        MESButton8                 matlab.ui.control.Button
        MESButton7                 matlab.ui.control.Button
        MESButton6                 matlab.ui.control.Button
        MESButton5                 matlab.ui.control.Button
        MESButton4                 matlab.ui.control.Button
        MESButton3                 matlab.ui.control.Button
        MESButton2                 matlab.ui.control.Button
        MESButton1                 matlab.ui.control.Button
        MESButton0                 matlab.ui.control.Button
        HoldLabel                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        callingFigure=[]; %handle to calling figure
        nnp=[]; % nnpapi
        callingPosition=[];
    end
    
    methods (Access = private)

        function ClearMessages(app)
            app.Messages.Value=''; % clear messages
        end

        function TopMessage(app,message)
            app.Messages.Value=vertcat(message, string(app.Messages.Value)); % put new message on top
        end
        
        function startTestData(app)
            % When starting, set 1F53.A and B to zero first
            message='Start TestData Failed'; % if any fail, start, use this message
            resp=app.nnp.write(7,'1F53',10,2,uint8([0,0]),'uint8');
            if resp==0
                % Then start script 1
                resp2=app.nnp.nmt(7,'8D',1); % Run MES TestData script
                if resp2==hex2dec('8D')
                    % Set 1F53.9 to 1
                    resp3=app.nnp.write(7,'1F53',9,uint8(1),'uint8');
                    if resp3==0
                        message='TestData Started';
                        % enable both panels
                        app.MES1Panel.Enable='on';
                        app.MES2Panel.Enable='on';
                    end
                end
            end
            app.TopMessage(message);
         end
        
        function startBPData(app)
            % When switching back to BPData, for bookkeeping
            % Disable the panels
            app.MES1Panel.Enable='off';
            app.MES2Panel.Enable='off';

            % send 0 to 1F53.A and B
            message='Stop TestData Failed'; % if any fail, start, use this message
            resp=app.nnp.write(7,'1F53',10,2,uint8([0,0]),'uint8');
            if resp==0
                % Set 1F53.9 to 0
                resp3=app.nnp.write(7,'1F53',9,uint8(0),'uint8');
                if resp3==0
                    pause(.5);% wait for rPDO to be set back
                    resp2=app.nnp.nmt(7,'AE',1); % Run MES TestData script
                    if resp2==hex2dec('AE')
                        message='TestData Stopped';
                    end
                end
            end
            app.TopMessage(message);
            % Do I want to stop the script here? Or stop when closing
            % figure?  Probably best to stop script
        end
        
        
        function setMESType(app,chan,mtype)
            % chan is MES1 or MES2
            % I could set them both with one message, but probably will want
            % to set them independently anyway
            % mtype is number from 0-11
            % Hold	    0	Low	1	    Med	2	High
            % Step	    3	Low	4	    Med	5	High
            % Ramp	    6	Up/Down	7	Up	8	Down
            % Twitch	9	Short	10	Long	11	Doublet
            % Send mtype to either 1F53.A or 1F53.B
            if chan==1 || chan==2 % chan has be be either 1 or 2
                subindex=9+chan;
                resp=app.nnp.write(7,'1F53',subindex,uint8(mtype),'uint8');
                if resp~=0 % error writing
                    app.TopMessage('Error writing mtype'); % only respond if fails
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin<2
                NNP=[];
            else
                NNP=varargin{1};
            end
            if isempty(NNP)
                app.nnp = NNPAPI();
                if isempty(app.nnp.port)
                    msgbox('No port chosen for NNP Access Point')
                    delete(app.nnp)
                    return
                end
            else
                app.nnp = NNP;
            end
            if nargin>2
                app.callingFigure=varargin{2};
                cp=app.callingFigure.Position; % get position of the figure that called
                mp=app.MESTestDataUIFigure.Position; % get current position
                xpos=cp(1)+cp(3)+1;
                ypos=cp(2);
                mp=[xpos,ypos,mp(3:4)];
                app.MESTestDataUIFigure.Position=mp;               
            end
        end

        % Button pushed function: MESButton0
        function MESButton0Pushed(app, event)
            app.setMESType(1,0);
            app.mtypesentEditField.Value=0;
        end

        % Button pushed function: MESButton1
        function MESButton1Pushed(app, event)
         app.setMESType(1,1);
         app.mtypesentEditField.Value=1;   
         pause(.5);
         app.mtypesentEditField.Value=0; 
        end

        % Button pushed function: MESButton2
        function MESButton2Pushed(app, event)
         app.setMESType(1,2);
         app.mtypesentEditField.Value=2;   
         pause(.5);
         app.mtypesentEditField.Value=0;     
        end

        % Button pushed function: MESButton3
        function MESButton3Pushed(app, event)
            app.setMESType(1,3);
            app.mtypesentEditField.Value=3;
            pause(.5);
            app.mtypesentEditField.Value=0;
        end

        % Button pushed function: MESButton4
        function MESButton4Pushed(app, event)
            app.setMESType(1,4);
            app.mtypesentEditField.Value=4;
            pause(.5);
            app.mtypesentEditField.Value=0;
        end

        % Button pushed function: MESButton5
        function MESButton5Pushed(app, event)
            app.setMESType(1,5);
            app.mtypesentEditField.Value=5;
            pause(.5);
            app.mtypesentEditField.Value=0;
        end

        % Button pushed function: MESButton6
        function MESButton6Pushed(app, event)
            app.setMESType(1,6);
            app.mtypesentEditField.Value=6;
            pause(.5);
            app.mtypesentEditField.Value=0;
        end

        % Button pushed function: MESButton7
        function MESButton7Pushed(app, event)
            app.setMESType(1,7);
            app.mtypesentEditField.Value=7;
            pause(.5);
            app.mtypesentEditField.Value=0;
        end

        % Button pushed function: MESButton8
        function MESButton8Pushed(app, event)
            app.setMESType(1,8);
            app.mtypesentEditField.Value=8;
            pause(.5);
            app.mtypesentEditField.Value=0;
        end

        % Button pushed function: MESButton9
        function MESButton9Pushed(app, event)
            app.setMESType(1,9);
            app.mtypesentEditField.Value=9;
            pause(.5);
            app.mtypesentEditField.Value=0;
        end

        % Button pushed function: MESButton10
        function MESButton10Pushed(app, event)
            app.setMESType(1,10);
            app.mtypesentEditField.Value=10;
            pause(.5);
            app.mtypesentEditField.Value=0;
        end

        % Button pushed function: MESButton11
        function MESButton11Pushed(app, event)
            app.setMESType(1,11);
            app.mtypesentEditField.Value=11;
            pause(.5);
            app.mtypesentEditField.Value=0;
        end

        % Button pushed function: MESButton0_2
        function MESButton0_2Pushed(app, event)
           app.setMESType(2,0);
           app.mtypesentEditField_2.Value=0;
        end

        % Button pushed function: MESButton1_2
        function MESButton1_2Pushed(app, event)
            app.setMESType(2,1);
           app.mtypesentEditField_2.Value=1;
            pause(.5);
            app.mtypesentEditField_2.Value=0;
        end

        % Button pushed function: MESButton2_2
        function MESButton2_2Pushed(app, event)
            app.setMESType(2,2);
            app.mtypesentEditField_2.Value=2;
            pause(.5);
            app.mtypesentEditField_2.Value=0;
        end

        % Button pushed function: MESButton3_2
        function MESButton3_2Pushed(app, event)
            app.setMESType(2,3);
            app.mtypesentEditField_2.Value=3;
            pause(.5);
            app.mtypesentEditField_2.Value=0;
        end

        % Button pushed function: MESButton4_2
        function MESButton4_2Pushed(app, event)
            app.setMESType(2,4);
            app.mtypesentEditField_2.Value=4;
            pause(.5);
            app.mtypesentEditField_2.Value=0;
        end

        % Button pushed function: MESButton5_2
        function MESButton5_2Pushed(app, event)
            app.setMESType(2,5);
            app.mtypesentEditField_2.Value=5;
            pause(.5);
            app.mtypesentEditField_2.Value=0;
        end

        % Button pushed function: MESButton6_2
        function MESButton6_2Pushed(app, event)
            app.setMESType(2,6);
            app.mtypesentEditField_2.Value=6;
            pause(.5);
            app.mtypesentEditField_2.Value=0;
        end

        % Button pushed function: MESButton7_2
        function MESButton7_2Pushed(app, event)
            app.setMESType(2,7);
            app.mtypesentEditField_2.Value=7;
            pause(.5);
            app.mtypesentEditField_2.Value=0;
        end

        % Button pushed function: MESButton8_2
        function MESButton8_2Pushed(app, event)
            app.setMESType(2,8);
            app.mtypesentEditField_2.Value=8;
            pause(.5);
            app.mtypesentEditField_2.Value=0;
        end

        % Button pushed function: MESButton9_2
        function MESButton9_2Pushed(app, event)
            app.setMESType(2,9);
            app.mtypesentEditField_2.Value=9;
            pause(.5);
            app.mtypesentEditField_2.Value=0;
        end

        % Button pushed function: MESButton10_2
        function MESButton10_2Pushed(app, event)
            app.setMESType(2,10);
            app.mtypesentEditField_2.Value=10;
            pause(.5);
            app.mtypesentEditField_2.Value=0;
        end

        % Button pushed function: MESButton11_2
        function MESButton11_2Pushed(app, event)
            app.setMESType(2,11);
            app.mtypesentEditField_2.Value=11;
            pause(.5);
            app.mtypesentEditField_2.Value=0;
        end

        % Close request function: MESTestDataUIFigure
        function MESTestDataUIFigureCloseRequest(app, event)
            if ishandle(app.callingFigure) % check that is is valid
                figure(app.callingFigure); % bring to front
            end
            delete(app)

        end

        % Button pushed function: BPMESButton
        function BPMESButtonPushed(app, event)
            app.startBPData();
        end

        % Button pushed function: TestDataButton
        function TestDataButtonPushed(app, event)
            app.startTestData();
        end

        % Button pushed function: ClearMessageButton
        function ClearMessageButtonPushed(app, event)
            app.ClearMessages();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MESTestDataUIFigure = uifigure('Visible', 'off');
            app.MESTestDataUIFigure.Name = 'MES Test Data';
            app.MESTestDataUIFigure.CloseRequestFcn = createCallbackFcn(app, @MESTestDataUIFigureCloseRequest, true);
            app.MES1Panel = uipanel(app.MESTestDataUIFigure);
            app.MES1Panel.Enable = 'off';
            app.MES1Panel.Title = 'MES 1';
            app.HoldLabel = uilabel(app.MES1Panel);
            app.HoldLabel.Text = 'Hold';
            app.MESButton0 = uibutton(app.MES1Panel, 'push');
            app.MESButton0.ButtonPushedFcn = createCallbackFcn(app, @MESButton0Pushed, true);
            app.MESButton0.Text = 'Low';
            app.MESButton1 = uibutton(app.MES1Panel, 'push');
            app.MESButton1.ButtonPushedFcn = createCallbackFcn(app, @MESButton1Pushed, true);
            app.MESButton1.Text = 'Med';
            app.MESButton2 = uibutton(app.MES1Panel, 'push');
            app.MESButton2.ButtonPushedFcn = createCallbackFcn(app, @MESButton2Pushed, true);
            app.MESButton2.Text = 'High';
            app.MESButton3 = uibutton(app.MES1Panel, 'push');
            app.MESButton3.ButtonPushedFcn = createCallbackFcn(app, @MESButton3Pushed, true);
            app.MESButton3.Text = 'Low';
            app.MESButton4 = uibutton(app.MES1Panel, 'push');
            app.MESButton4.ButtonPushedFcn = createCallbackFcn(app, @MESButton4Pushed, true);
            app.MESButton4.Text = 'Med';
            app.MESButton5 = uibutton(app.MES1Panel, 'push');
            app.MESButton5.ButtonPushedFcn = createCallbackFcn(app, @MESButton5Pushed, true);
            app.MESButton5.Text = 'High';
            app.MESButton6 = uibutton(app.MES1Panel, 'push');
            app.MESButton6.ButtonPushedFcn = createCallbackFcn(app, @MESButton6Pushed, true);
            app.MESButton6.Text = 'Up Down';
            app.MESButton7 = uibutton(app.MES1Panel, 'push');
            app.MESButton7.ButtonPushedFcn = createCallbackFcn(app, @MESButton7Pushed, true);
            app.MESButton7.Text = 'Up hold';
            app.MESButton8 = uibutton(app.MES1Panel, 'push');
            app.MESButton8.ButtonPushedFcn = createCallbackFcn(app, @MESButton8Pushed, true);
            app.MESButton8.Text = 'Down hold';
            app.MESButton9 = uibutton(app.MES1Panel, 'push');
            app.MESButton9.ButtonPushedFcn = createCallbackFcn(app, @MESButton9Pushed, true);
            app.MESButton9.Text = 'Short';
            app.MESButton10 = uibutton(app.MES1Panel, 'push');
            app.MESButton10.ButtonPushedFcn = createCallbackFcn(app, @MESButton10Pushed, true);
            app.MESButton10.Text = 'Long';
            app.MESButton11 = uibutton(app.MES1Panel, 'push');
            app.MESButton11.ButtonPushedFcn = createCallbackFcn(app, @MESButton11Pushed, true);
            app.MESButton11.Text = 'Doublet';
            app.mtypesentEditFieldLabel = uilabel(app.MES1Panel);
            app.mtypesentEditFieldLabel.Text = 'mtype sent';
            app.mtypesentEditField = uieditfield(app.MES1Panel, 'numeric');
            app.StepLabel = uilabel(app.MES1Panel);
            app.StepLabel.Text = 'Step';
            app.RampLabel = uilabel(app.MES1Panel);
            app.RampLabel.Text = 'Ramp';
            app.TwitchLabel = uilabel(app.MES1Panel);
            app.TwitchLabel.Text = 'Twitch';
            app.MES2Panel = uipanel(app.MESTestDataUIFigure);
            app.MES2Panel.Enable = 'off';
            app.MES2Panel.Title = 'MES 2';
            app.HoldLabel_2 = uilabel(app.MES2Panel);
            app.HoldLabel_2.Text = 'Hold';
            app.MESButton0_2 = uibutton(app.MES2Panel, 'push');
            app.MESButton0_2.ButtonPushedFcn = createCallbackFcn(app, @MESButton0_2Pushed, true);
            app.MESButton0_2.Text = 'Low';
            app.MESButton1_2 = uibutton(app.MES2Panel, 'push');
            app.MESButton1_2.ButtonPushedFcn = createCallbackFcn(app, @MESButton1_2Pushed, true);
            app.MESButton1_2.Text = 'Med';
            app.MESButton2_2 = uibutton(app.MES2Panel, 'push');
            app.MESButton2_2.ButtonPushedFcn = createCallbackFcn(app, @MESButton2_2Pushed, true);
            app.MESButton2_2.Text = 'High';
            app.MESButton3_2 = uibutton(app.MES2Panel, 'push');
            app.MESButton3_2.ButtonPushedFcn = createCallbackFcn(app, @MESButton3_2Pushed, true);
            app.MESButton3_2.Text = 'Low';
            app.MESButton4_2 = uibutton(app.MES2Panel, 'push');
            app.MESButton4_2.ButtonPushedFcn = createCallbackFcn(app, @MESButton4_2Pushed, true);
            app.MESButton4_2.Text = 'Med';
            app.MESButton5_2 = uibutton(app.MES2Panel, 'push');
            app.MESButton5_2.ButtonPushedFcn = createCallbackFcn(app, @MESButton5_2Pushed, true);
            app.MESButton5_2.Text = 'High';
            app.MESButton6_2 = uibutton(app.MES2Panel, 'push');
            app.MESButton6_2.ButtonPushedFcn = createCallbackFcn(app, @MESButton6_2Pushed, true);
            app.MESButton6_2.Text = 'Up Down';
            app.MESButton7_2 = uibutton(app.MES2Panel, 'push');
            app.MESButton7_2.ButtonPushedFcn = createCallbackFcn(app, @MESButton7_2Pushed, true);
            app.MESButton7_2.Text = 'Up hold';
            app.MESButton8_2 = uibutton(app.MES2Panel, 'push');
            app.MESButton8_2.ButtonPushedFcn = createCallbackFcn(app, @MESButton8_2Pushed, true);
            app.MESButton8_2.Text = 'Down hold';
            app.MESButton9_2 = uibutton(app.MES2Panel, 'push');
            app.MESButton9_2.ButtonPushedFcn = createCallbackFcn(app, @MESButton9_2Pushed, true);
            app.MESButton9_2.Text = 'Short';
            app.MESButton10_2 = uibutton(app.MES2Panel, 'push');
            app.MESButton10_2.ButtonPushedFcn = createCallbackFcn(app, @MESButton10_2Pushed, true);
            app.MESButton10_2.Text = 'Long';
            app.MESButton11_2 = uibutton(app.MES2Panel, 'push');
            app.MESButton11_2.ButtonPushedFcn = createCallbackFcn(app, @MESButton11_2Pushed, true);
            app.MESButton11_2.Text = 'Doublet';
            app.mtypesentEditField_2Label = uilabel(app.MES2Panel);
            app.mtypesentEditField_2Label.Text = 'mtype sent';
            app.mtypesentEditField_2 = uieditfield(app.MES2Panel, 'numeric');
            app.StepLabel_2 = uilabel(app.MES2Panel);
            app.StepLabel_2.Text = 'Step';
            app.RampLabel_2 = uilabel(app.MES2Panel);
            app.RampLabel_2.Text = 'Ramp';
            app.TwitchLabel_2 = uilabel(app.MES2Panel);
            app.TwitchLabel_2.Text = 'Twitch';
            app.BPMESButton = uibutton(app.MESTestDataUIFigure, 'push');
            app.BPMESButton.ButtonPushedFcn = createCallbackFcn(app, @BPMESButtonPushed, true);
            app.BPMESButton.Text = 'BP MES';
            app.TestDataButton = uibutton(app.MESTestDataUIFigure, 'push');
            app.TestDataButton.ButtonPushedFcn = createCallbackFcn(app, @TestDataButtonPushed, true);
            app.TestDataButton.Text = 'Test Data';
            app.Messages = uitextarea(app.MESTestDataUIFigure);
            app.ClearMessageButton = uibutton(app.MESTestDataUIFigure, 'push');
            app.ClearMessageButton.ButtonPushedFcn = createCallbackFcn(app, @ClearMessageButtonPushed, true);
            app.ClearMessageButton.Text = 'clear';
            app.MessagesLabel = uilabel(app.MESTestDataUIFigure);
            app.MessagesLabel.Text = 'Messages';
            % Show the figure after all components are created
            app.MESTestDataUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mesTestData(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MESTestDataUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MESTestDataUIFigure)
        end
    end
end