classdef nnpClinicalSuite < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ClinicalSuiteUIFigure        matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        StatusMenu                   matlab.ui.container.Menu
        StatusMenu_2                 matlab.ui.container.Menu
        PMVersionMenu                matlab.ui.container.Menu
        DateTimeMenu                 matlab.ui.container.Menu
        GetDateTimeMenu              matlab.ui.container.Menu
        SetDateTimeMenu              matlab.ui.container.Menu
        RemoteModuleCurrentConfigurationMenu  matlab.ui.container.Menu
        ActiveFGMenu                 matlab.ui.container.Menu
        ScriptStatusMenu             matlab.ui.container.Menu
        ODTestMenu                   matlab.ui.container.Menu
        PowerMenu                    matlab.ui.container.Menu
        NetworkONMenu                matlab.ui.container.Menu
        NetworkOFFMenu               matlab.ui.container.Menu
        NetworkScannerMenu_2         matlab.ui.container.Menu
        WaitingMenu                  matlab.ui.container.Menu
        PMShutdownMenu               matlab.ui.container.Menu
        ConfigMenu                   matlab.ui.container.Menu
        SystemDiagramMenu            matlab.ui.container.Menu
        PDOsetupMenu                 matlab.ui.container.Menu
        RMsetupMenu                  matlab.ui.container.Menu
        AccsetupMenu                 matlab.ui.container.Menu
        ExerciseMenu                 matlab.ui.container.Menu
        ChargingMenu                 matlab.ui.container.Menu
        EMGMenu                      matlab.ui.container.Menu
        RecordRawMenu                matlab.ui.container.Menu
        RecordProcessedMenu          matlab.ui.container.Menu
        BPTestMenu                   matlab.ui.container.Menu
        ObjectDictionaryMenu         matlab.ui.container.Menu
        ODTestMenu_2                 matlab.ui.container.Menu
        KeyODMenu                    matlab.ui.container.Menu
        SavePMODMenu                 matlab.ui.container.Menu
        F57Menu                      matlab.ui.container.Menu
        F53Menu                      matlab.ui.container.Menu
        PatternsMenu                 matlab.ui.container.Menu
        ProfileMenu                  matlab.ui.container.Menu
        PatternEditMenu              matlab.ui.container.Menu
        ActionPatternsMenu           matlab.ui.container.Menu
        ScriptsMenu                  matlab.ui.container.Menu
        ScriptStatusMenu_2           matlab.ui.container.Menu
        DisableMenu                  matlab.ui.container.Menu
        EnableMenu                   matlab.ui.container.Menu
        StartupMenu                  matlab.ui.container.Menu
        EditScriptsMenu              matlab.ui.container.Menu
        ScriptOrderDefaultMenu       matlab.ui.container.Menu
        LogMenu                      matlab.ui.container.Menu
        DataLogMenu                  matlab.ui.container.Menu
        BackupRestoreMemoryMenu      matlab.ui.container.Menu
        ReadRestoreFileMenu          matlab.ui.container.Menu
        DiagnosticMenu               matlab.ui.container.Menu
        NetworkScannerMenu           matlab.ui.container.Menu
        PhoneAppMenu                 matlab.ui.container.Menu
        ODTestMenu_3                 matlab.ui.container.Menu
        EMGTestDataMenu              matlab.ui.container.Menu
        BootloaderMenu               matlab.ui.container.Menu
        PMBootloaderMenu             matlab.ui.container.Menu
        RemoteModulesBLMenu          matlab.ui.container.Menu
        MiscMenu                     matlab.ui.container.Menu
        CommunicationsChecklistMenu  matlab.ui.container.Menu
        NotesMenu                    matlab.ui.container.Menu
        MarkerSetMenu                matlab.ui.container.Menu
        HelpMenu                     matlab.ui.container.Menu
        TooltipsMenu                 matlab.ui.container.Menu
        EnableTooltipsMenu           matlab.ui.container.Menu
        DisableTooltipsMenu          matlab.ui.container.Menu
        FullStatusButton             matlab.ui.control.Button
        ParticipantDropDown          matlab.ui.control.DropDown
        ClearMessageButton           matlab.ui.control.Button
        PMVersionButton              matlab.ui.control.Button
        PMLastErrEditField           matlab.ui.control.EditField
        ResetUSBButton               matlab.ui.control.Button
        MessagesTextArea             matlab.ui.control.TextArea
        MessagesTextAreaLabel        matlab.ui.control.Label
        Commands                     matlab.ui.container.TabGroup
        PowerTab                     matlab.ui.container.Tab
        VnetEditField                matlab.ui.control.NumericEditField
        VnetEditFieldLabel           matlab.ui.control.Label
        VnetUpdatePanel              matlab.ui.container.Panel
        PMVNETEditField              matlab.ui.control.NumericEditField
        UpdateButton                 matlab.ui.control.Button
        EnterWaitingButton           matlab.ui.control.Button
        NetworkONButton              matlab.ui.control.Button
        NetworkOFFButton             matlab.ui.control.Button
        PMShutdownButton             matlab.ui.control.Button
        ScriptsTab                   matlab.ui.container.Tab
        PMScriptOrderDefaultButton   matlab.ui.control.Button
        RunningButton                matlab.ui.control.Button
        SavePMODButton               matlab.ui.control.Button
        ScriptEdit                   matlab.ui.control.Button
        StartupScriptButton          matlab.ui.control.Button
        EnableScriptsButton          matlab.ui.control.Button
        DisableScriptsButton         matlab.ui.control.Button
        ControlTab                   matlab.ui.container.Tab
        AccSetupButton               matlab.ui.control.Button
        DisplayRawEMGCueButton       matlab.ui.control.Button
        RawEMGRecordingCueButton     matlab.ui.control.Button
        RecordProcessedButton        matlab.ui.control.Button
        DisplayProcessedButton       matlab.ui.control.Button
        DisplayRawEMGButton          matlab.ui.control.Button
        RawEMGRecordingButton        matlab.ui.control.Button
        ConfigurationsTab            matlab.ui.container.Tab
        SmartChargeButton            matlab.ui.control.Button
        KeyPMODButton                matlab.ui.control.Button
        PDOSetupReadButton           matlab.ui.control.Button
        getPMDateTime                matlab.ui.control.Button
        PM1F57Button                 matlab.ui.control.Button
        RemoteModuleSetupButton      matlab.ui.control.Button
        setPMDateTime                matlab.ui.control.Button
        BackupRestoreMemoryButton    matlab.ui.control.Button
        PDOSetupButton               matlab.ui.control.Button
        RemoteModuleStatusButton     matlab.ui.control.Button
        ReadRestoreUserDataButton    matlab.ui.control.Button
        Patterns                     matlab.ui.container.Tab
        SystemDiagramButton          matlab.ui.control.Button
        DisableScriptsButton_2       matlab.ui.control.Button
        DisablescriptsbeforepatterneditingLabel  matlab.ui.control.Label
        ActiveFGButton               matlab.ui.control.Button
        ActionPatternMap             matlab.ui.control.Button
        Exercise                     matlab.ui.control.Button
        ProfilerButton               matlab.ui.control.Button
        PatternEdit                  matlab.ui.control.Button
        LogTab                       matlab.ui.container.Tab
        DataLogButton                matlab.ui.control.Button
        DiagnosticTab                matlab.ui.container.Tab
        PM1F53Button                 matlab.ui.control.Button
        EMGTestDataButton            matlab.ui.control.Button
        getStatusButton              matlab.ui.control.Button
        PhoneAppButton               matlab.ui.control.Button
        MarkerSet                    matlab.ui.control.Button
        BTTestButton                 matlab.ui.control.Button
        NotesButton                  matlab.ui.control.Button
        CommChecklistButton          matlab.ui.control.Button
        RMBootloaderButton           matlab.ui.control.Button
        PMBootloaderButton           matlab.ui.control.Button
        ODTestButton                 matlab.ui.control.Button
        NetworkScannerButton         matlab.ui.control.Button
        NNPAccessPointButton         matlab.ui.control.StateButton
        ConfigAPButton               matlab.ui.control.Button
    end

    % Notes
    % In this clicnal suite, I want to add tools that I would use to set
    % various parameters, based upon recorded EMG values, and that sort of
    % thing.
    
    properties (Access = private)
    end
    
    properties (Access = public)
        nnp = []; % Description
        enableToolTips = false;
        userFolders=struct;  % userfiles, code, user(path), config, data, log
        mainDir=[];
        oldPath=[];
        
    end
    
    methods (Access = private)
         
        function ResponseMessage(app,good,message)
            if good
                dmessage=message;
                tbc=[1,1,1];
            else
                dmessage=app.nnp.lastError;
                tbc=[1,1,.7];
            end
            app.MessagesTextArea.Value=dmessage;
            app.MessagesTextArea.BackgroundColor=tbc;
        end
        
        function verifyNnpTimeout(app)
            % Read the ap settings and make sure the nnp.timeout is consistent with the number of retries
            % read current ap settings 
            
            % calculate needed minimum timeout
            % rcompare with nnp.timeout
            % give warning that timeout needs to be increased or just set it higher or open apsettings
            
        end
        
        function TopMessage(app,message)
             app.MessagesTextArea.Value=vertcat(message, string(app.MessagesTextArea.Value)); % put new message on top
        end
        
        
        function ClearMessages(app)
            app.MessagesTextArea.Value=''; % clear messages
        end
        
        function getActiveFG(app,nodes)
            % read in active FG in the nodes listed in array nodes
            str=sprintf('Active FG\n');
            for i=1:size(nodes,2)
                FGstr='';
                resp=app.nnp.read(nodes(i),'3200',2,'uint8');
                if length(resp)==4
                    FGstr=sprintf('%d ',resp);
                    str=[str,sprintf('\nNode %d: %s',nodes(i),FGstr)];
                end
            end
            %
            h = msgbox(str, 'ActiveFG', 'modal');
            uiwait(h);
        end
        
        function getStatus_FG_Scripts(app)
            % Combine Status, Active FG and Script Status into one
            % Need to check for conflicting variables
            % plan to use one string for all
             str=''; %initialize string
            respstr=pmDateTimeGet(app.nnp);
            if ~isempty(respstr)
                str=respstr;
            end
            resp=double(app.nnp.read(7,'1018',3,'uint32')); % read revision number to test communications
            %app.ResponseMessage(~isempty(resp),sprintf('PM version %d',resp));
            if ~isempty(resp)
                pmStr=sprintf('\nPM version %d\n',resp);
                str=[str, pmStr];
            end
            % Get network voltage
             % 3010.0, uns8, 0x55, Network Voltage *10
                 resp=double(app.nnp.read(7,'3010',0,1,'uint8')); % Vnet*10
                if(length(resp)==1) %valid packet
                   Vnet=resp/10;
                   app.VnetEditField.Value=Vnet;  
                else
                    Vnet=0;
                end
                vnetStr=sprintf('Vnet = %2.1f -- ',Vnet);
                str=[str, vnetStr];
            % Next get Status with getStatus
           
         %  [modes, temp, vsys, net, rssi, lqi, group, lpm] = getStatus(NNP, nodes)
         % Could look only at nodes 1:9 for nodes
             nodes2scan=uint8(1:9);
            [modes, temp, vsys, net, rssi] = app.nnp.getStatus(nodes2scan);
            if ~isempty(modes)
                if net
                    str =  [str,sprintf('Network is ON')];
                else
                    str = [str, sprintf('Network is OFF')];
                end
                str = [str, sprintf('\n\nPM Temp: %4.1f⁰C\nRSSI: %4.1fdBm\nVSYS: %4.1fV\nPM mode: %s\n\n', temp, rssi, vsys, modes{7})];
                %insert here a read of battery voltages from 3000.0D
                bat=double(app.nnp.read(7,'3000',13,'uint8')); % read revision number to test communications
                if length(bat)<20

                else
                    batV1 = (bat( 7)+bat( 8)*256)/1000; 
                    batV2 = (bat( 9)+bat(10)*256)/1000; 
                    batV3 = (bat(11)+bat(12)*256)/1000; %in V
   
                    str = [str, sprintf('Vbatt: %4.2f,  %4.2f,  %4.2f\n\n',batV1,batV2,batV3)];
                end
                if net
                    str = [str, sprintf('Note: Nodes not listed on network may be in bootloader mode.\nNodes also may take up to 2s to update.\n\n')];
                    for i=[1:6 8:length(modes)]
                        % here I could look at modes{i} to see if
                        % strcomp='Patient' to determine whether to scan
                        str=[str, sprintf('Node %2d: %s\n', i, modes{i})];
                    end
                else
                    str = [str,'Note: Nodes not listed because network is off'];
                end
 %At this point, we need to postpone writing the message box until we have the other info               
              %  h = msgbox(str, 'Get Status', 'modal');
              %  uiwait(h);


% Add getActive FG
% Assumes an input called nodes which it must check
% for now assume nodes=[1:6] which are stim nodes
             nodes=[1:6];
            % read in active FG in the nodes listed in array nodes
            str2=sprintf('\nActive FG');
            for i=1:size(nodes,2)
                FGstr='';
                resp=app.nnp.read(nodes(i),'3200',2,'uint8');
                if length(resp)==4
                    FGstr=sprintf('%d ',resp);
                    str2=[str2,sprintf('\nNode %d: %s',nodes(i),FGstr)];
                end
            end
            %
% combine strings 1 and 2
        str=[str,str2]; %may need a \n in there somewhere

% Wait to write until all info collected
%            h = msgbox(str2, 'ActiveFG', 'modal');
%            uiwait(h);
        
% Script status was originally written to diplay on the dmessage box
% display a simple list of scripts which are currently running
            % return error if not all scriptControls are read
            % from scriptedit.mlapp:
            %should call populateLibraryTable before callng this
            %Can only read 50 bytes at a time so no more than 12 script controls
            resp = app.nnp.read(7, '1f51', 1,12, 'uint32'); %read script 1-12 controls
            if length(resp) ~= 12
                return
            else
                scriptControl(1:12) = double(resp);
            end
            resp = app.nnp.read(7, '1f51', 13,12, 'uint32'); %read script 13-24 controls
            if length(resp) ~= 12
                return
            else
                scriptControl(13:24) = double(resp);
            end
            resp =  app.nnp.read(7, '1f51', 25,1, 'uint32'); %read script 25 controls
            if length(resp) ~= 1
                return
            else
                scriptControl(25) = double(resp);
            end

            % for the first pass, I just want a list of SP running continuous
            % in this case bit 1=1,  otherwise ignore
            running=[];
            n=0;
            for row=1:25 %using row because they were in table in mlapp
                run = bitand(scriptControl(row), hex2dec('03'));
%                 fprintf('sp %d, run %d\n',row,run);
                if run==2
                    n=n+1;
                    running(n)=row;
                end
            end
            if n>0
                runninglist=sprintf('%d ',running); %string with space between
            else
                runninglist=sprintf('no scripts running');
            end
% combine the strings
            runninglist=sprintf('\n\nScripts running: %s',runninglist); %add CR before
            str=[str,runninglist];
                h = msgbox(str, 'Get Date Status FG Scripts', 'modal');
                uiwait(h);
            else
                h = msgbox('Could not get Status', 'Get Status Error', 'error', 'modal');
                uiwait(h);
            end
        end % End of getStatus_FG_Scripts
    end
    
    methods (Access = public)
        
        function returnFocus(app)
%            drawnow;
           figure(app.ClinicalSuiteUIFigure); 
        end
        
        function FillUserDropDown(app)
            % read all the subdirectories in the current folder and fill in
            % the dropdown options
            dd = dir(app.userFolders.userfiles);
            isub = [dd(:).isdir]; %# returns logical vector
            dirList = {dd(isub).name}';
            dirList(ismember(dirList,{'.','..'})) = [];
            none={'none'};
            dirList=[none;dirList]; % add a non selection to force
            % set dropdown values
            app.ParticipantDropDown.Items=dirList;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.NNPAccessPointButton.Value = 0;
            [p,~,~]=fileparts(mfilename('fullpath')); % path of the running funtction
            app.mainDir=p; % path of the running funtction
            app.userFolders.userfiles=[p,'\UserFiles'];% userfiles, code, user, config, data, log,
            app.FillUserDropDown();
            app.Commands.SelectedTab=app.Patterns;
        end

        % Button pushed function: ConfigAPButton
        function ConfigAPButtonPushed(app, event)
            radioconfig(app.nnp,app.ClinicalSuiteUIFigure);
        end

        % Callback function: BPTestMenu, BTTestButton
        function BTTestButtonPushed(app, event)
            bptestf(app.nnp,app.ClinicalSuiteUIFigure);
        end

        % Callback function: NetworkScannerButton, NetworkScannerMenu, 
        % NetworkScannerMenu_2
        function NetworkScannerButtonPushed(app, event)
            netscannerg(app.nnp,app.ClinicalSuiteUIFigure,app.userFolders);
        end

        % Close request function: ClinicalSuiteUIFigure
        function ClinicalSuiteUIFigureCloseRequest(app, event)
            if ~isempty(app.nnp)
                delete(app.nnp)
            end
            delete(app)
        end

        % Value changed function: NNPAccessPointButton
        function NNPAccessPointButtonValueChanged(app, event)
            if ~isempty(app.nnp)
                delete(app.nnp)
            end
            
            if app.NNPAccessPointButton.Value
                app.NNPAccessPointButton.Text = 'NNP Access Point-Select Port';
                app.NNPAccessPointButton.BackgroundColor=[.9,.9,.9];
                while true
                    app.TopMessage('Opening...');
                    app.nnp = NNPAPI();
                    app.returnFocus(); 
                    if isempty(app.nnp.port)
                        resp = questdlg('No port chosen for NNP Access Point.  Plug in AccessPoint and try again.  If you do not want to try again, press No and the application will close');
                        if isequal(resp, 'No')
%                             close(app.fillintheblank); % if I want to close something
                            return;
                        end
                    else                        
                        break;
                    end
                end
%                 app.nnp.timeout=1;  % set new default timeout that matches 5 retries
                app.nnp.timeout=.5;  % set new default timeout that matches 2 retries (total of 3 tries) at 100ms radio +23ms for WOR
                % how long Matlab waits for usb response - may be some
                % jitter.
                app.NNPAccessPointButton.Text = 'NNP Access Point Open';
                app.returnFocus();    
                app.NNPAccessPointButton.BackgroundColor=[0.81,1.00,0.67];
                app.TopMessage('Finished opening Access Point');
            else
                app.NNPAccessPointButton.Text = 'NNP Access Point Closed';
                app.returnFocus();
                app.NNPAccessPointButton.BackgroundColor=[.96,.96,.96];
                app.TopMessage('Finished Closing Access Point');
            end

        end

        % Callback function: CommChecklistButton, CommunicationsChecklistMenu
        function CommChecklistButtonPushed(app, event)
            commchecklist();
        end

        % Callback function: ProfileMenu, ProfilerButton
        function ProfilerButtonPushed(app, event)

            chanProfiler(app.nnp,app.ClinicalSuiteUIFigure,app.userFolders); % added user folders to open standard names

        end

        % Callback function: PMBootloaderButton, PMBootloaderMenu
        function PMBootloaderButtonPushed(app, event)
            pmbootloader(app.nnp,app.ClinicalSuiteUIFigure);
        end

        % Callback function: RMBootloaderButton, RemoteModulesBLMenu
        function RMBootloaderButtonPushed(app, event)
            rmbootloader(app.nnp,app.ClinicalSuiteUIFigure);
        end

        % Callback function: DisableMenu, DisableScriptsButton, 
        % DisableScriptsButton_2
        function DisableScriptsButtonPushed(app, event)
            app.nnp.enterWaiting();
            response=app.nnp.nmt(7, '8F'); %disable scripts
            app.ResponseMessage(response==hex2dec('8F'),'Scripts Disabled');
%             if response==hex2dec('8F')
%                 dmessage='Scripts Disabled';
%                 tbc=[1,1,1];
%             else
%                 dmessage=app.nnp.lastError;
%                 tbc=[1,1,.7];
%             end
%             app.MessagesTextArea.Value=dmessage;
%             app.MessagesTextArea.BackgroundColor=tbc;
        end

        % Callback function: EnableMenu, EnableScriptsButton
        function EnableScriptsButtonPushed(app, event)
            app.nnp.enterWaiting();            
            response=app.nnp.nmt(7, '8E'); %enable scripts
            app.ResponseMessage(response==hex2dec('8E'),'Scripts Enabled');
%             if response==hex2dec('8E')
%                 dmessage='Scripts Enabled';
%                 tbc=[1,1,1];
%             else
%                 dmessage=app.nnp.lastError;
%                 tbc=[1,1,.7];
%             end
%             app.MessagesTextArea.Value=dmessage;
%             app.MessagesTextArea.BackgroundColor=tbc;
        end

        % Callback function: StartupMenu, StartupScriptButton
        function StartupScriptButtonPushed(app, event)
            response=app.nnp.nmt(7, '8E'); %enable scripts
            if response==hex2dec('8E')
                dmessage='Scripts Enabled';
                response=app.nnp.nmt(7,'B3',7); % Run startup script
                if response==hex2dec('B3')
                    dmessage='Scripts enabled and Startup Ran';
                    tbc=[1,1,1];
                else
                    dmessage=['Scripts Enabled, but ',app.nnp.lastError];
                    tbc=[1,1,.7];
                end
            else
                dmessage=app.nnp.lastError;
            end
            app.MessagesTextArea.Value=dmessage;
            app.MessagesTextArea.BackgroundColor=tbc;
        end

        % Callback function: PMShutdownButton, PMShutdownMenu
        function PMShutdownButtonPushed(app, event)
            app.nnp.enterWaiting();
            % Add confirmation dialog here
            resp = questdlg('Do you want to power down the Power Module? You will need to use control tower to restart!');
            if isequal(resp, 'Yes')
                response=app.nnp.nmt(7, '9E'); %enable scripts
                if response==hex2dec('9E')
                    dmessage='PM Shutdown';
                    tbc=[1,1,1];
                else
                    dmessage=app.nnp.lastError;
                    tbc=[1,1,.7];
                end
            else
                dmessage='Cancelled';
                tbc=[1,1,1];
            end
            app.MessagesTextArea.Value=dmessage;
            app.MessagesTextArea.BackgroundColor=tbc;
        end

        % Callback function: PMVersionButton, PMVersionMenu
        function PMVersionButtonPushed(app, event)
           str='';
            respstr=pmDateTimeGet(app.nnp);
            if ~isempty(respstr)
                str=respstr;
            end
            resp=double(app.nnp.read(7,'1018',3,'uint32')); % read revision number to test communications
            %app.ResponseMessage(~isempty(resp),sprintf('PM version %d',resp));
            if ~isempty(resp)
                str=[str, sprintf('\nPM version %d',resp)];
% get temp
             temp=app.nnp.getTemp(7); % PM temperture  
             if ~isempty(temp)
                 str=[str, sprintf('\n PMTemp: %4.1f⁰C',temp)];
             end
% get vbatt
                bat=double(app.nnp.read(7,'3000',13,'uint8')); % read revision number to test communications
                if length(bat)<20

                else
                    batV1 = (bat( 7)+bat( 8)*256)/1000; 
                    batV2 = (bat( 9)+bat(10)*256)/1000; 
                    batV3 = (bat(11)+bat(12)*256)/1000; %in V
   
                    str = [str, sprintf('\nVbatt: %4.2f,  %4.2f,  %4.2f',batV1,batV2,batV3)];
                end
            else
                str='';
            end
            app.ResponseMessage(~isempty(resp),str);

%             if ~isempty(resp) % if the return is not empty, give revision number
%                 dmessage=sprintf('PM version %d',resp);
%                 tbc=[1,1,1];
%             else
%                 dmessage=app.nnp.lastError;
%                 tbc=[1,1,.7];
%             end
%             app.MessagesTextArea.Value=dmessage;
%             app.MessagesTextArea.BackgroundColor=tbc;
        end

        % Callback function: ODTestButton, ODTestMenu, ODTestMenu_2, 
        % ODTestMenu_3
        function ODTestButtonPushed(app, event)
            odtest(app.nnp,app.ClinicalSuiteUIFigure);
        end

        % Callback function: ReadRestoreFileMenu, ReadRestoreUserDataButton
        function ReadRestoreUserDataButtonPushed(app, event)
            pmRestoreFileDisplay(app.nnp,app.ClinicalSuiteUIFigure);
        end

        % Callback function: RawEMGRecordingButton, RecordRawMenu
        function RawEMGRecordingButtonPushed(app, event)
            emgRecordRaw(app.nnp,app.ClinicalSuiteUIFigure,app.userFolders);
        end

        % Callback function: NotesButton, NotesMenu
        function NotesButtonPushed(app, event)
            edit clinicalsuitenotes.txt;
        end

        % Callback function: DataLogButton, DataLogMenu
        function DataLogButtonPushed(app, event)
            pmReadRestoreLog(app.nnp,app.ClinicalSuiteUIFigure,app.userFolders);
        end

        % Callback function: RemoteModuleCurrentConfigurationMenu, 
        % RemoteModuleStatusButton
        function RemoteModuleStatusButtonPushed(app, event)
            rmParametersStatus(app.nnp,app.ClinicalSuiteUIFigure,app.userFolders);
        end

        % Button pushed function: DisplayRawEMGButton
        function DisplayRawEMGButtonPushed(app, event)
            emgDisplayRaw(); % This has an additional input for filename or folder
        end

        % Button pushed function: DisplayProcessedButton
        function DisplayProcessedButtonPushed(app, event)
            emgDisplayProcessed();
        end

        % Callback function: PDOSetupButton, PDOsetupMenu
        function PDOSetupButtonPushed(app, event)
            pdoConfig(app.nnp,app.ClinicalSuiteUIFigure,app.userFolders);
        end

        % Callback function: BackupRestoreMemoryButton, 
        % BackupRestoreMemoryMenu
        function BackupRestoreMemoryButtonPushed(app, event)
            backupRestoreMemory(app.nnp,app.ClinicalSuiteUIFigure,app.userFolders);
        end

        % Callback function: MarkerSet, MarkerSetMenu
        function MarkerSetButtonPushed(app, event)
           nnpsXmlSetsTranslate(app.ClinicalSuiteUIFigure);
        end

        % Callback function: PatternEdit, PatternEditMenu
        function PatternEditButtonPushed(app, event)
            patternEditKeyPad(app.nnp,app.ClinicalSuiteUIFigure,app.userFolders);
        end

        % Button pushed function: ScriptEdit
        function ScriptEditButtonPushed(app, event)
           scriptedit(app.nnp,app.ClinicalSuiteUIFigure,app.userFolders); 
        end

        % Callback function: RecordProcessedButton, RecordProcessedMenu
        function RecordProcessedButtonPushed(app, event)
            emgRecordProcessedTab(app.nnp,app.ClinicalSuiteUIFigure,app.userFolders);
        end

        % Button pushed function: ResetUSBButton
        function ResetUSBButtonPushed(app, event)
            app.nnp.refresh();
        end

        % Callback function: NetworkOFFButton, NetworkOFFMenu
        function NetworkOFFButtonPushed(app, event)
            if app.nnp.enterWaiting()
                if app.nnp.networkOff()
                    dmessage = 'Network Off';
                     tbc=[1,1,1];
                else
                    dmessage=app.nnp.lastError;
                    tbc=[1,1,.7];
                end
            else
                dmessage=app.nnp.lastError;
                    tbc=[1,1,.7];
            end
            app.MessagesTextArea.Value=dmessage;
            app.MessagesTextArea.BackgroundColor=tbc;
           
        end

        % Callback function: NetworkONButton, NetworkONMenu
        function NetworkONButtonPushed(app, event)
            if app.nnp.enterWaiting()
                if app.nnp.networkOn()
                    dmessage = 'Network On';
                    tbc=[1,1,1];
                else
                    dmessage=app.nnp.lastError;
                    tbc=[1,1,.7];
                end
            else
                dmessage=app.nnp.lastError;
                tbc=[1,1,.7];
            end
            app.MessagesTextArea.Value=dmessage;
            app.MessagesTextArea.BackgroundColor=tbc;
        end

        % Callback function: RMsetupMenu, RemoteModuleSetupButton
        function RemoteModuleSetupButtonPushed(app, event)
            rmSetup(app.nnp,app.ClinicalSuiteUIFigure,app.userFolders);
        end

        % Callback function: Exercise, ExerciseMenu
        function ExerciseButtonPushed(app, event)
           exerciseSetup(app.nnp,app.ClinicalSuiteUIFigure,app.userFolders); %pass figure handle
        end

        % Button pushed function: RawEMGRecordingCueButton
        function RawEMGRecordingCueButtonPushed(app, event)
            emgRecordRawCue(app.nnp,app.ClinicalSuiteUIFigure,app.userFolders);
        end

        % Button pushed function: DisplayRawEMGCueButton
        function DisplayRawEMGCueButtonPushed(app, event)
            emgDisplayRawCue(); 
        end

        % Callback function: AccSetupButton, AccsetupMenu
        function AccSetupButtonPushed(app, event)
            accSettings(app.nnp,app.ClinicalSuiteUIFigure,app.userFolders);
        end

        % Callback function: F57Menu, PM1F57Button
        function PM1F57ButtonPushed(app, event)
            readWrite1F57(app.nnp,app.ClinicalSuiteUIFigure,app.userFolders);
        end

        % Callback function: F53Menu, PM1F53Button
        function PM1F53ButtonPushed(app, event)
             readWrite1F53(app.nnp,app.ClinicalSuiteUIFigure);
        end

        % Menu selected function: EnableTooltipsMenu
        function EnableTooltipsMenuSelected(app, event)
            app.enableToolTips = true;
            % Here I need to include all the objects for which I want to set tooltips, and set the tips here.
        end

        % Menu selected function: DisableTooltipsMenu
        function DisableTooltipsMenuSelected(app, event)
            app.enableToolTips = false;
            % Here I need to include all the tooltips that I want to clear, and make them all ' '.
        end

        % Callback function: PhoneAppButton, PhoneAppMenu
        function PhoneAppButtonPushed(app, event)
            phoneAppFunctions(app.nnp,app.ClinicalSuiteUIFigure);
        end

        % Callback function: GetDateTimeMenu, getPMDateTime
        function getPMDateTimeButtonPushed(app, event)
            resp=pmDateTimeGet(app.nnp);
            if ischar(resp)
                dmessage=resp;
%                dmessage='test';
            else
                dmessage='Error reading data/time from PM';
            end
            app.MessagesTextArea.Value=dmessage;
            
        end

        % Callback function: SetDateTimeMenu, setPMDateTime
        function setPMDateTimeButtonPushed(app, event)
            resp=pmDateTimeSet(app.nnp);
            if resp==1
                dmessage='Error setting PM RTC';
            else
                dmessage='PM RTC Set';
            end
            app.MessagesTextArea.Value=dmessage;
        end

        % Callback function: StatusMenu_2, getStatusButton
        function getStatusButtonPushed(app, event)
            [modes, temp, vsys, net, rssi] = app.nnp.getStatus();
            if ~isempty(modes)
                if net
                    str = sprintf('Network is ON');
                else
                    str = sprintf('Network is OFF');
                end
                str = [str, sprintf('\n\nPM Temp: %4.1f⁰C\nRSSI: %4.1fdBm\nVSYS: %4.1fV\nPM mode: %s\n\n', temp, rssi, vsys, modes{7})];
                %insert here a read of battery voltages from 3000.0D
                bat=double(app.nnp.read(7,'3000',13,'uint8')); % read revision number to test communications
                if length(bat)<20

                else
                    batV1 = (bat( 7)+bat( 8)*256)/1000; 
                    batV2 = (bat( 9)+bat(10)*256)/1000; 
                    batV3 = (bat(11)+bat(12)*256)/1000; %in V
   
                    str = [str, sprintf('Vbatt: %4.2f,  %4.2f,  %4.2f\n\n',batV1,batV2,batV3)];
                end
                if net
                    str = [str, sprintf('Note: Nodes not listed on network may be in bootloader mode.\nNodes also may take up to 2s to update.\n\n')];
                    for i=[1:6 8:length(modes)]
                        str=[str, sprintf('Node %2d: %s\n', i, modes{i})];
                    end
                else
                    str = [str,'Note: Nodes not listed because network is off'];
                end
                
                h = msgbox(str, 'Get Status', 'modal');
                uiwait(h);
            else
                h = msgbox('Could not get Status', 'Get Status Error', 'error', 'modal');
                uiwait(h);
            end
               
        end

        % Callback function: ActionPatternMap, ActionPatternsMenu
        function ActionPatternMapButtonPushed(app, event)
            actionPatternMap(app.nnp,app.ClinicalSuiteUIFigure,app.userFolders);
        end

        % Callback function: SavePMODButton, SavePMODMenu
        function SavePMODButtonPushed(app, event)
            if app.nnp.saveOD(7)
                    dmessage = 'PM OD Saved';
            else
                dmessage = 'Failed PM OD Save';
            end
            app.TopMessage(dmessage);
        end

        % Button pushed function: ClearMessageButton
        function ClearMessageButtonPushed(app, event)
            app.ClearMessages();
        end

        % Callback function: RunningButton, ScriptStatusMenu, 
        % ScriptStatusMenu_2
        function RunningButtonPushed(app, event)
            % display a simple list of scripts which are currently running
            % return error if not all scriptControls are read
            % from scriptedit.mlapp:
            %should call populateLibraryTable before callng this
            %Can only read 50 bytes at a time so no more than 12 script controls
            resp = app.nnp.read(7, '1f51', 1,12, 'uint32'); %read script 1-12 controls
            if length(resp) ~= 12
                return
            else
                scriptControl(1:12) = double(resp);
            end
            resp = app.nnp.read(7, '1f51', 13,12, 'uint32'); %read script 13-24 controls
            if length(resp) ~= 12
                return
            else
                scriptControl(13:24) = double(resp);
            end
            resp =  app.nnp.read(7, '1f51', 25,1, 'uint32'); %read script 25 controls
            if length(resp) ~= 1
                return
            else
                scriptControl(25) = double(resp);
            end


            % I may use order in the second pass on this to put them in the order of
            % execution
            % order = [];
            % resp = app.nnp.read(7, '1f56', 1, 'uint8'); %read script order
            % if length(resp) == 25
            %     order = double(resp);
            % end

            %Script Controls bit definitions:
            % bit  0   : 1=run once
            % bit  1   : 1=run continuous, 0=halt
            % bit  2   : 1 = reinit global var table
            % bit  3-7 : reserved for future use
            % bit  8-15: script ID
            % bit 16-23: reserved for future use
            % bit 24-30: error code
            % bit 31   : 1= run once successful execution

            %Colors:
            % green: if run continuous (run = 2)
            % blue: run once complete (run = 0, runresult = 1)
            % yellow: run once not complete running (run = 1, runresult = 0)
            % red: error
            % white: stopped
            % gray: unused

            % for the first pass, I just want a list of SP running continuous
            % in this case bit 1=1,  otherwise ignore
            running=[];
            n=0;
            for row=1:25 %using row because they were in table in mlapp
                run = bitand(scriptControl(row), hex2dec('03'));
%                 fprintf('sp %d, run %d\n',row,run);
                if run==2
                    n=n+1;
                    running(n)=row;
                end
            end
            if n>0
                runninglist=sprintf('%d ',running); %string with space between
            else
                runninglist=sprintf('no scripts running');
            end
            % first pass I will write to command window
            dmessage=sprintf('sp = %s\n',runninglist);
            app.TopMessage(dmessage);
        end

        % Callback function: ActiveFGButton, ActiveFGMenu
        function ActiveFGButtonPushed(app, event)
        app.getActiveFG([1:6]); % need way to configure number of nodes here    
       
        end

        % Callback function: EMGTestDataButton, EMGTestDataMenu
        function EMGTestDataButtonPushed(app, event)
             emgTestDataGraph(app.nnp,app.ClinicalSuiteUIFigure);
        end

        % Callback function: SystemDiagramButton, SystemDiagramMenu
        function SystemDiagramButtonPushed(app, event)
            displayNNPSystemDiagram(app.ClinicalSuiteUIFigure,app.userFolders);  % need to add return figure to mlapp on closure.  
            % how best to pass diagram or configuration data back and forth
            % between CS and this script.   For now just read data from
            % .mat file
        end

        % Callback function: PMScriptOrderDefaultButton, 
        % ScriptOrderDefaultMenu
        function PMScriptOrderDefaultButtonPushed(app, event)
            PMScriptOrder = uint8([1  21   9   8   6   2  15  12  11   3  18  14  16  25  10  13  17  23  19  24   7   5  20  22   4]);
            resp=app.nnp.write(7, '1F56',1,PMScriptOrder,'uint8');
            if resp==0
                dMessage='PM Script Order Written';
            else
                dMessage='Error writing PM Script';
            end
            app.TopMessage(dMessage);
        end

        % Button pushed function: PDOSetupReadButton
        function PDOSetupReadButtonPushed(app, event)
            pdoConfigRead(app.nnp,app.ClinicalSuiteUIFigure,app.userFolders);
        end

        % Callback function: KeyODMenu, KeyPMODButton
        function KeyPMODButtonPushed(app, event)
            keyODParameters(app.nnp,app.ClinicalSuiteUIFigure);
        end

        % Callback function: EnterWaitingButton, WaitingMenu
        function EnterWaitingButtonPushed(app, event)
                if ~app.nnp.enterWaiting() %all nodes
                    dMessage='Error Enter Waiting';
                else
                    dMessage='Enter Waiting';
                end
                 app.TopMessage(dMessage);
        end

        % Callback function: ChargingMenu, SmartChargeButton
        function SmartChargeButtonPushed(app, event)
            setSmartChargingLevels(app.nnp,app.ClinicalSuiteUIFigure);
        end

        % Value changed function: ParticipantDropDown
        function ParticipantDropDownValueChanged(app, event)
            value = app.ParticipantDropDown.Value;
            if ~strcmp(value,'none')
            userFileDir=app.userFolders.userfiles;
            app.userFolders.code=value;
            app.userFolders.user=fullfile(userFileDir, value); % userfiles, code, user, config, data, log,
            app.userFolders.config=fullfile(app.userFolders.user,'Config');
            app.userFolders.data=fullfile(app.userFolders.user,'Data');
            app.userFolders.log=fullfile(app.userFolders.user,'Log');
            app.ParticipantDropDown.BackgroundColor=[1,1,1];
            app.oldPath=cd( app.userFolders.user); %in case I want an undo
            else
                %set folder to default userfiles path
                app.oldPath=cd(app.userFolders.userfiles);
                app.FillUserDropDown(); %read directory again
            end  
        end

        % Button pushed function: FullStatusButton
        function FullStatusButtonPushed(app, event)
             app.getStatus_FG_Scripts();
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            % Write network voltage
            newVnet=uint8(app.PMVNETEditField.Value*10);
            resp=double(app.nnp.write(7,'3010',0,newVnet,'uint8')); % Vnet*10
            if resp==0
                dMessage='Vnet read';
            else
                dMessage='Error reading Vnet';
            end
            app.TopMessage(dMessage);
            % Get network voltage
             % 3010.0, uns8, 0x55, Network Voltage *10
                resp=double(app.nnp.read(7,'3010',0,1,'uint8')); % Vnet*10
                if(length(resp)==1) %valid packet
                   Vnet=resp/10;
                    app.VnetEditField.Value=Vnet;  
                    app.VnetEditField.Enable = true;
                    app.VnetEditFieldLabel.Enable = true;
                else
                    
                end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ClinicalSuiteUIFigure = uifigure('Visible', 'off');
            app.ClinicalSuiteUIFigure.Name = 'NNP Clinical Suite';
            app.ClinicalSuiteUIFigure.CloseRequestFcn = createCallbackFcn(app, @ClinicalSuiteUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.ClinicalSuiteUIFigure);
            app.FileMenu.Text = 'File';
            app.StatusMenu = uimenu(app.ClinicalSuiteUIFigure);
            app.StatusMenu.Text = 'Status';
            app.StatusMenu_2 = uimenu(app.StatusMenu);
            app.StatusMenu_2.MenuSelectedFcn = createCallbackFcn(app, @getStatusButtonPushed, true);
            app.StatusMenu_2.Text = 'Status';
            app.PMVersionMenu = uimenu(app.StatusMenu);
            app.PMVersionMenu.MenuSelectedFcn = createCallbackFcn(app, @PMVersionButtonPushed, true);
            app.PMVersionMenu.Text = 'PM Version';
            app.DateTimeMenu = uimenu(app.StatusMenu);
            app.DateTimeMenu.Text = 'Date/Time';
            app.GetDateTimeMenu = uimenu(app.DateTimeMenu);
            app.GetDateTimeMenu.MenuSelectedFcn = createCallbackFcn(app, @getPMDateTimeButtonPushed, true);
            app.GetDateTimeMenu.Text = 'Get Date Time';
            app.SetDateTimeMenu = uimenu(app.DateTimeMenu);
            app.SetDateTimeMenu.MenuSelectedFcn = createCallbackFcn(app, @setPMDateTimeButtonPushed, true);
            app.SetDateTimeMenu.Text = 'Set Date Time';
            app.RemoteModuleCurrentConfigurationMenu = uimenu(app.StatusMenu);
            app.RemoteModuleCurrentConfigurationMenu.MenuSelectedFcn = createCallbackFcn(app, @RemoteModuleStatusButtonPushed, true);
            app.RemoteModuleCurrentConfigurationMenu.Text = 'Remote Module Current Configuration';
            app.ActiveFGMenu = uimenu(app.StatusMenu);
            app.ActiveFGMenu.MenuSelectedFcn = createCallbackFcn(app, @ActiveFGButtonPushed, true);
            app.ActiveFGMenu.Text = 'Active FG';
            app.ScriptStatusMenu = uimenu(app.StatusMenu);
            app.ScriptStatusMenu.MenuSelectedFcn = createCallbackFcn(app, @RunningButtonPushed, true);
            app.ScriptStatusMenu.Text = 'Script Status';
            app.ODTestMenu = uimenu(app.StatusMenu);
            app.ODTestMenu.MenuSelectedFcn = createCallbackFcn(app, @ODTestButtonPushed, true);
            app.ODTestMenu.Text = 'OD Test';
            app.PowerMenu = uimenu(app.ClinicalSuiteUIFigure);
            app.PowerMenu.Text = 'Power';
            app.NetworkONMenu = uimenu(app.PowerMenu);
            app.NetworkONMenu.MenuSelectedFcn = createCallbackFcn(app, @NetworkONButtonPushed, true);
            app.NetworkONMenu.Text = 'Network ON';
            app.NetworkOFFMenu = uimenu(app.PowerMenu);
            app.NetworkOFFMenu.MenuSelectedFcn = createCallbackFcn(app, @NetworkOFFButtonPushed, true);
            app.NetworkOFFMenu.Text = 'Network OFF';
            app.NetworkScannerMenu_2 = uimenu(app.PowerMenu);
            app.NetworkScannerMenu_2.MenuSelectedFcn = createCallbackFcn(app, @NetworkScannerButtonPushed, true);
            app.NetworkScannerMenu_2.Text = 'NetworkScanner';
            app.WaitingMenu = uimenu(app.PowerMenu);
            app.WaitingMenu.MenuSelectedFcn = createCallbackFcn(app, @EnterWaitingButtonPushed, true);
            app.WaitingMenu.Text = 'Waiting';
            app.PMShutdownMenu = uimenu(app.PowerMenu);
            app.PMShutdownMenu.MenuSelectedFcn = createCallbackFcn(app, @PMShutdownButtonPushed, true);
            app.PMShutdownMenu.Text = 'PM Shutdown';
            app.ConfigMenu = uimenu(app.ClinicalSuiteUIFigure);
            app.ConfigMenu.Text = 'Config';
            app.SystemDiagramMenu = uimenu(app.ConfigMenu);
            app.SystemDiagramMenu.MenuSelectedFcn = createCallbackFcn(app, @SystemDiagramButtonPushed, true);
            app.SystemDiagramMenu.Text = 'System Diagram';
            app.PDOsetupMenu = uimenu(app.ConfigMenu);
            app.PDOsetupMenu.MenuSelectedFcn = createCallbackFcn(app, @PDOSetupButtonPushed, true);
            app.PDOsetupMenu.Text = 'PDO setup';
            app.RMsetupMenu = uimenu(app.ConfigMenu);
            app.RMsetupMenu.MenuSelectedFcn = createCallbackFcn(app, @RemoteModuleSetupButtonPushed, true);
            app.RMsetupMenu.Text = 'RM setup';
            app.AccsetupMenu = uimenu(app.ConfigMenu);
            app.AccsetupMenu.MenuSelectedFcn = createCallbackFcn(app, @AccSetupButtonPushed, true);
            app.AccsetupMenu.Text = 'Acc setup';
            app.ExerciseMenu = uimenu(app.ConfigMenu);
            app.ExerciseMenu.MenuSelectedFcn = createCallbackFcn(app, @ExerciseButtonPushed, true);
            app.ExerciseMenu.Text = 'Exercise';
            app.ChargingMenu = uimenu(app.ConfigMenu);
            app.ChargingMenu.MenuSelectedFcn = createCallbackFcn(app, @SmartChargeButtonPushed, true);
            app.ChargingMenu.Text = 'Charging';
            app.EMGMenu = uimenu(app.ConfigMenu);
            app.EMGMenu.Text = 'EMG';
            app.RecordRawMenu = uimenu(app.EMGMenu);
            app.RecordRawMenu.MenuSelectedFcn = createCallbackFcn(app, @RawEMGRecordingButtonPushed, true);
            app.RecordRawMenu.Text = 'Record Raw';
            app.RecordProcessedMenu = uimenu(app.EMGMenu);
            app.RecordProcessedMenu.MenuSelectedFcn = createCallbackFcn(app, @RecordProcessedButtonPushed, true);
            app.RecordProcessedMenu.Text = 'Record Processed';
            app.BPTestMenu = uimenu(app.EMGMenu);
            app.BPTestMenu.MenuSelectedFcn = createCallbackFcn(app, @BTTestButtonPushed, true);
            app.BPTestMenu.Text = 'BP Test';
            app.ObjectDictionaryMenu = uimenu(app.ConfigMenu);
            app.ObjectDictionaryMenu.Text = 'Object Dictionary';
            app.ODTestMenu_2 = uimenu(app.ObjectDictionaryMenu);
            app.ODTestMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ODTestButtonPushed, true);
            app.ODTestMenu_2.Text = 'OD Test';
            app.KeyODMenu = uimenu(app.ObjectDictionaryMenu);
            app.KeyODMenu.MenuSelectedFcn = createCallbackFcn(app, @KeyPMODButtonPushed, true);
            app.KeyODMenu.Text = 'Key OD';
            app.SavePMODMenu = uimenu(app.ObjectDictionaryMenu);
            app.SavePMODMenu.MenuSelectedFcn = createCallbackFcn(app, @SavePMODButtonPushed, true);
            app.SavePMODMenu.Text = 'Save PM OD';
            app.F57Menu = uimenu(app.ObjectDictionaryMenu);
            app.F57Menu.MenuSelectedFcn = createCallbackFcn(app, @PM1F57ButtonPushed, true);
            app.F57Menu.Text = '1F57';
            app.F53Menu = uimenu(app.ObjectDictionaryMenu);
            app.F53Menu.MenuSelectedFcn = createCallbackFcn(app, @PM1F53ButtonPushed, true);
            app.F53Menu.Text = '1F53';
            app.PatternsMenu = uimenu(app.ClinicalSuiteUIFigure);
            app.PatternsMenu.Text = 'Patterns';
            app.ProfileMenu = uimenu(app.PatternsMenu);
            app.ProfileMenu.MenuSelectedFcn = createCallbackFcn(app, @ProfilerButtonPushed, true);
            app.ProfileMenu.Text = 'Profile';
            app.PatternEditMenu = uimenu(app.PatternsMenu);
            app.PatternEditMenu.MenuSelectedFcn = createCallbackFcn(app, @PatternEditButtonPushed, true);
            app.PatternEditMenu.Text = 'Pattern Edit';
            app.ActionPatternsMenu = uimenu(app.PatternsMenu);
            app.ActionPatternsMenu.MenuSelectedFcn = createCallbackFcn(app, @ActionPatternMapButtonPushed, true);
            app.ActionPatternsMenu.Text = 'Action Patterns';
            app.ScriptsMenu = uimenu(app.ClinicalSuiteUIFigure);
            app.ScriptsMenu.Text = 'Scripts';
            app.ScriptStatusMenu_2 = uimenu(app.ScriptsMenu);
            app.ScriptStatusMenu_2.MenuSelectedFcn = createCallbackFcn(app, @RunningButtonPushed, true);
            app.ScriptStatusMenu_2.Text = 'Script Status';
            app.DisableMenu = uimenu(app.ScriptsMenu);
            app.DisableMenu.MenuSelectedFcn = createCallbackFcn(app, @DisableScriptsButtonPushed, true);
            app.DisableMenu.Text = 'Disable';
            app.EnableMenu = uimenu(app.ScriptsMenu);
            app.EnableMenu.MenuSelectedFcn = createCallbackFcn(app, @EnableScriptsButtonPushed, true);
            app.EnableMenu.Text = 'Enable';
            app.StartupMenu = uimenu(app.ScriptsMenu);
            app.StartupMenu.MenuSelectedFcn = createCallbackFcn(app, @StartupScriptButtonPushed, true);
            app.StartupMenu.Text = 'Startup';
            app.EditScriptsMenu = uimenu(app.ScriptsMenu);
            app.EditScriptsMenu.Text = 'Edit Scripts';
            app.ScriptOrderDefaultMenu = uimenu(app.ScriptsMenu);
            app.ScriptOrderDefaultMenu.MenuSelectedFcn = createCallbackFcn(app, @PMScriptOrderDefaultButtonPushed, true);
            app.ScriptOrderDefaultMenu.Text = 'Script Order Default';
            app.LogMenu = uimenu(app.ClinicalSuiteUIFigure);
            app.LogMenu.Text = 'Log';
            app.DataLogMenu = uimenu(app.LogMenu);
            app.DataLogMenu.MenuSelectedFcn = createCallbackFcn(app, @DataLogButtonPushed, true);
            app.DataLogMenu.Text = 'Data Log';
            app.BackupRestoreMemoryMenu = uimenu(app.LogMenu);
            app.BackupRestoreMemoryMenu.MenuSelectedFcn = createCallbackFcn(app, @BackupRestoreMemoryButtonPushed, true);
            app.BackupRestoreMemoryMenu.Text = 'Backup/Restore Memory';
            app.ReadRestoreFileMenu = uimenu(app.LogMenu);
            app.ReadRestoreFileMenu.MenuSelectedFcn = createCallbackFcn(app, @ReadRestoreUserDataButtonPushed, true);
            app.ReadRestoreFileMenu.Text = 'Read Restore File';
            app.DiagnosticMenu = uimenu(app.ClinicalSuiteUIFigure);
            app.DiagnosticMenu.Text = 'Diagnostic';
            app.NetworkScannerMenu = uimenu(app.DiagnosticMenu);
            app.NetworkScannerMenu.MenuSelectedFcn = createCallbackFcn(app, @NetworkScannerButtonPushed, true);
            app.NetworkScannerMenu.Text = 'Network Scanner';
            app.PhoneAppMenu = uimenu(app.DiagnosticMenu);
            app.PhoneAppMenu.MenuSelectedFcn = createCallbackFcn(app, @PhoneAppButtonPushed, true);
            app.PhoneAppMenu.Text = 'Phone App ';
            app.ODTestMenu_3 = uimenu(app.DiagnosticMenu);
            app.ODTestMenu_3.MenuSelectedFcn = createCallbackFcn(app, @ODTestButtonPushed, true);
            app.ODTestMenu_3.Text = 'OD Test';
            app.EMGTestDataMenu = uimenu(app.DiagnosticMenu);
            app.EMGTestDataMenu.MenuSelectedFcn = createCallbackFcn(app, @EMGTestDataButtonPushed, true);
            app.EMGTestDataMenu.Text = 'EMG Test Data';
            app.BootloaderMenu = uimenu(app.ClinicalSuiteUIFigure);
            app.BootloaderMenu.Text = 'Bootloader';
            app.PMBootloaderMenu = uimenu(app.BootloaderMenu);
            app.PMBootloaderMenu.MenuSelectedFcn = createCallbackFcn(app, @PMBootloaderButtonPushed, true);
            app.PMBootloaderMenu.Text = 'PM Bootloader';
            app.RemoteModulesBLMenu = uimenu(app.BootloaderMenu);
            app.RemoteModulesBLMenu.MenuSelectedFcn = createCallbackFcn(app, @RMBootloaderButtonPushed, true);
            app.RemoteModulesBLMenu.Text = 'Remote Modules BL';
            app.MiscMenu = uimenu(app.ClinicalSuiteUIFigure);
            app.MiscMenu.Text = 'Misc';
            app.CommunicationsChecklistMenu = uimenu(app.MiscMenu);
            app.CommunicationsChecklistMenu.MenuSelectedFcn = createCallbackFcn(app, @CommChecklistButtonPushed, true);
            app.CommunicationsChecklistMenu.Text = 'Communications Checklist';
            app.NotesMenu = uimenu(app.MiscMenu);
            app.NotesMenu.MenuSelectedFcn = createCallbackFcn(app, @NotesButtonPushed, true);
            app.NotesMenu.Text = 'Notes';
            app.MarkerSetMenu = uimenu(app.MiscMenu);
            app.MarkerSetMenu.MenuSelectedFcn = createCallbackFcn(app, @MarkerSetButtonPushed, true);
            app.MarkerSetMenu.Text = 'MarkerSet';
            app.HelpMenu = uimenu(app.ClinicalSuiteUIFigure);
            app.HelpMenu.Text = 'Help';
            app.TooltipsMenu = uimenu(app.HelpMenu);
            app.TooltipsMenu.Text = 'Tooltips';
            app.EnableTooltipsMenu = uimenu(app.TooltipsMenu);
            app.EnableTooltipsMenu.MenuSelectedFcn = createCallbackFcn(app, @EnableTooltipsMenuSelected, true);
            app.EnableTooltipsMenu.Text = 'Enable Tooltips';
            app.DisableTooltipsMenu = uimenu(app.TooltipsMenu);
            app.DisableTooltipsMenu.MenuSelectedFcn = createCallbackFcn(app, @DisableTooltipsMenuSelected, true);
            app.DisableTooltipsMenu.Text = 'Disable Tooltips';
            app.ConfigAPButton = uibutton(app.ClinicalSuiteUIFigure, 'push');
            app.ConfigAPButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigAPButtonPushed, true);
            app.ConfigAPButton.Tooltip = {'Configure access point radio settings'};
            app.ConfigAPButton.Text = 'Radio Configuration';
            app.NNPAccessPointButton = uibutton(app.ClinicalSuiteUIFigure, 'state');
            app.NNPAccessPointButton.ValueChangedFcn = createCallbackFcn(app, @NNPAccessPointButtonValueChanged, true);
            app.NNPAccessPointButton.Tooltip = {'Enable or disable access point'};
            app.NNPAccessPointButton.Text = 'NNP Access Point Open';
            app.Commands = uitabgroup(app.ClinicalSuiteUIFigure);
            app.PowerTab = uitab(app.Commands);
            app.PowerTab.Title = 'Power';
            app.PMShutdownButton = uibutton(app.PowerTab, 'push');
            app.PMShutdownButton.ButtonPushedFcn = createCallbackFcn(app, @PMShutdownButtonPushed, true);
            app.PMShutdownButton.Text = {'PM'; 'Shutdown'};
            app.NetworkOFFButton = uibutton(app.PowerTab, 'push');
            app.NetworkOFFButton.ButtonPushedFcn = createCallbackFcn(app, @NetworkOFFButtonPushed, true);
            app.NetworkOFFButton.Text = {'Network '; 'OFF'};
            app.NetworkONButton = uibutton(app.PowerTab, 'push');
            app.NetworkONButton.ButtonPushedFcn = createCallbackFcn(app, @NetworkONButtonPushed, true);
            app.NetworkONButton.Tooltip = {'Turn network on and set put in app mode'};
            app.NetworkONButton.Text = {'Network '; 'ON'};
            app.EnterWaitingButton = uibutton(app.PowerTab, 'push');
            app.EnterWaitingButton.ButtonPushedFcn = createCallbackFcn(app, @EnterWaitingButtonPushed, true);
            app.EnterWaitingButton.Text = {'Enter '; 'Waiting'};
            app.VnetUpdatePanel = uipanel(app.PowerTab);
            app.VnetUpdatePanel.Title = 'Vnet Update';
            app.UpdateButton = uibutton(app.VnetUpdatePanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.PMVNETEditField = uieditfield(app.VnetUpdatePanel, 'numeric');
            app.PMVNETEditField.Value = 8.5;
            app.VnetEditFieldLabel = uilabel(app.PowerTab);
            app.VnetEditFieldLabel.Enable = 'off';
            app.VnetEditFieldLabel.Text = 'Vnet';
            app.VnetEditField = uieditfield(app.PowerTab, 'numeric');
            app.VnetEditField.Editable = 'off';
            app.VnetEditField.Enable = 'off';
            app.ScriptsTab = uitab(app.Commands);
            app.ScriptsTab.Title = 'Scripts';
            app.DisableScriptsButton = uibutton(app.ScriptsTab, 'push');
            app.DisableScriptsButton.ButtonPushedFcn = createCallbackFcn(app, @DisableScriptsButtonPushed, true);
            app.DisableScriptsButton.Tooltip = {'Disable scripts in the ower module'};
            app.DisableScriptsButton.Text = {'Disable '; 'PM Scripts'};
            app.EnableScriptsButton = uibutton(app.ScriptsTab, 'push');
            app.EnableScriptsButton.ButtonPushedFcn = createCallbackFcn(app, @EnableScriptsButtonPushed, true);
            app.EnableScriptsButton.Tooltip = {'enable scripts in the power module'};
            app.EnableScriptsButton.Text = {'Enable '; 'PM Scripts'};
            app.StartupScriptButton = uibutton(app.ScriptsTab, 'push');
            app.StartupScriptButton.ButtonPushedFcn = createCallbackFcn(app, @StartupScriptButtonPushed, true);
            app.StartupScriptButton.Tooltip = {'Run the startup script which is currently loaded in the power module'};
            app.StartupScriptButton.Text = {'Run Startup'; 'Script'};
            app.ScriptEdit = uibutton(app.ScriptsTab, 'push');
            app.ScriptEdit.ButtonPushedFcn = createCallbackFcn(app, @ScriptEditButtonPushed, true);
            app.ScriptEdit.Tooltip = {'Edit, assemble, download and manage scripts on power module'};
            app.ScriptEdit.Text = {'Edit'; 'Scripts'};
            app.SavePMODButton = uibutton(app.ScriptsTab, 'push');
            app.SavePMODButton.ButtonPushedFcn = createCallbackFcn(app, @SavePMODButtonPushed, true);
            app.SavePMODButton.Tooltip = {'Save current PM object dictionary as default'};
            app.SavePMODButton.Text = 'Save PM OD';
            app.RunningButton = uibutton(app.ScriptsTab, 'push');
            app.RunningButton.ButtonPushedFcn = createCallbackFcn(app, @RunningButtonPushed, true);
            app.RunningButton.Text = {'Currently'; 'Running'; 'Scripts'};
            app.PMScriptOrderDefaultButton = uibutton(app.ScriptsTab, 'push');
            app.PMScriptOrderDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @PMScriptOrderDefaultButtonPushed, true);
            app.PMScriptOrderDefaultButton.Tooltip = {'[1  21   9   8   6   2  15  12  11   3  18  14  16  25  10  13  17  23  19  24   7   5  20  22   4]'};
            app.PMScriptOrderDefaultButton.Text = {'PM Script'; 'Order Default'};
            app.ControlTab = uitab(app.Commands);
            app.ControlTab.Title = 'Control';
            app.RawEMGRecordingButton = uibutton(app.ControlTab, 'push');
            app.RawEMGRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @RawEMGRecordingButtonPushed, true);
            app.RawEMGRecordingButton.Tooltip = {'Record raw EMG and save to file'};
            app.RawEMGRecordingButton.Text = {'Raw EMG'; 'Recording'};
            app.DisplayRawEMGButton = uibutton(app.ControlTab, 'push');
            app.DisplayRawEMGButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayRawEMGButtonPushed, true);
            app.DisplayRawEMGButton.Tooltip = {'Display raw EMG from file'};
            app.DisplayRawEMGButton.Text = {'Display'; 'Raw'};
            app.DisplayProcessedButton = uibutton(app.ControlTab, 'push');
            app.DisplayProcessedButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayProcessedButtonPushed, true);
            app.DisplayProcessedButton.Tooltip = {'Display processed EMG from file'};
            app.DisplayProcessedButton.Text = {'Display'; 'Processed'};
            app.RecordProcessedButton = uibutton(app.ControlTab, 'push');
            app.RecordProcessedButton.ButtonPushedFcn = createCallbackFcn(app, @RecordProcessedButtonPushed, true);
            app.RecordProcessedButton.Tooltip = {'Record EMG features and processed EMG from PM scripts'};
            app.RecordProcessedButton.Text = {'Record'; 'Processed'};
            app.RawEMGRecordingCueButton = uibutton(app.ControlTab, 'push');
            app.RawEMGRecordingCueButton.ButtonPushedFcn = createCallbackFcn(app, @RawEMGRecordingCueButtonPushed, true);
            app.RawEMGRecordingCueButton.Tooltip = {'Record Raw EMG while providing visual cues'};
            app.RawEMGRecordingCueButton.Text = {'Raw EMG'; 'Cue'};
            app.DisplayRawEMGCueButton = uibutton(app.ControlTab, 'push');
            app.DisplayRawEMGCueButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayRawEMGCueButtonPushed, true);
            app.DisplayRawEMGCueButton.Tooltip = {'Display raw EMG from file which included cues'};
            app.DisplayRawEMGCueButton.Text = {'Display'; 'Raw Cue'};
            app.AccSetupButton = uibutton(app.ControlTab, 'push');
            app.AccSetupButton.ButtonPushedFcn = createCallbackFcn(app, @AccSetupButtonPushed, true);
            app.AccSetupButton.Tooltip = {'Set up Aux control accelerometer settings'};
            app.AccSetupButton.Text = 'Acc Setup';
            app.ConfigurationsTab = uitab(app.Commands);
            app.ConfigurationsTab.Title = 'Config';
            app.ReadRestoreUserDataButton = uibutton(app.ConfigurationsTab, 'push');
            app.ReadRestoreUserDataButton.ButtonPushedFcn = createCallbackFcn(app, @ReadRestoreUserDataButtonPushed, true);
            app.ReadRestoreUserDataButton.Tooltip = {'Read and display PM restore file and some of the remote module parameters'};
            app.ReadRestoreUserDataButton.Text = {'Read'; 'Restore File'};
            app.RemoteModuleStatusButton = uibutton(app.ConfigurationsTab, 'push');
            app.RemoteModuleStatusButton.ButtonPushedFcn = createCallbackFcn(app, @RemoteModuleStatusButtonPushed, true);
            app.RemoteModuleStatusButton.Tooltip = {'Display and save the current remote module configurations as a text file'};
            app.RemoteModuleStatusButton.Text = {'Remote Mod'; 'Configuration'; 'Status'};
            app.PDOSetupButton = uibutton(app.ConfigurationsTab, 'push');
            app.PDOSetupButton.ButtonPushedFcn = createCallbackFcn(app, @PDOSetupButtonPushed, true);
            app.PDOSetupButton.Tooltip = {'Set up PDOs for PM and remote modules'};
            app.PDOSetupButton.Text = {'PDO'; 'Setup'};
            app.BackupRestoreMemoryButton = uibutton(app.ConfigurationsTab, 'push');
            app.BackupRestoreMemoryButton.ButtonPushedFcn = createCallbackFcn(app, @BackupRestoreMemoryButtonPushed, true);
            app.BackupRestoreMemoryButton.Tooltip = {'Backup and restore memory locations in the power module and remote modules'};
            app.BackupRestoreMemoryButton.Text = {'Backup/Restore'; 'Memory'};
            app.setPMDateTime = uibutton(app.ConfigurationsTab, 'push');
            app.setPMDateTime.ButtonPushedFcn = createCallbackFcn(app, @setPMDateTimeButtonPushed, true);
            app.setPMDateTime.Tooltip = {'Set date and time in the power module'};
            app.setPMDateTime.Text = {'Set PM'; 'Date Time'};
            app.RemoteModuleSetupButton = uibutton(app.ConfigurationsTab, 'push');
            app.RemoteModuleSetupButton.ButtonPushedFcn = createCallbackFcn(app, @RemoteModuleSetupButtonPushed, true);
            app.RemoteModuleSetupButton.Tooltip = {'Set up parameters for remote modules'};
            app.RemoteModuleSetupButton.Text = {'Remote '; 'Module'; 'Setup'};
            app.PM1F57Button = uibutton(app.ConfigurationsTab, 'push');
            app.PM1F57Button.ButtonPushedFcn = createCallbackFcn(app, @PM1F57ButtonPushed, true);
            app.PM1F57Button.Tooltip = {'Read and write PM OD index 1F57 which contains persistent parameters for users'};
            app.PM1F57Button.Text = 'PM 1F57';
            app.getPMDateTime = uibutton(app.ConfigurationsTab, 'push');
            app.getPMDateTime.ButtonPushedFcn = createCallbackFcn(app, @getPMDateTimeButtonPushed, true);
            app.getPMDateTime.Tooltip = {'Set date and time in the power module'};
            app.getPMDateTime.Text = {'Get PM'; 'Date Time'};
            app.PDOSetupReadButton = uibutton(app.ConfigurationsTab, 'push');
            app.PDOSetupReadButton.ButtonPushedFcn = createCallbackFcn(app, @PDOSetupReadButtonPushed, true);
            app.PDOSetupReadButton.Tooltip = {'Set up PDOs for PM and remote modules'};
            app.PDOSetupReadButton.Text = {'PDO'; 'Setup'; 'Read'};
            app.KeyPMODButton = uibutton(app.ConfigurationsTab, 'push');
            app.KeyPMODButton.ButtonPushedFcn = createCallbackFcn(app, @KeyPMODButtonPushed, true);
            app.KeyPMODButton.Text = 'Key PM OD';
            app.SmartChargeButton = uibutton(app.ConfigurationsTab, 'push');
            app.SmartChargeButton.ButtonPushedFcn = createCallbackFcn(app, @SmartChargeButtonPushed, true);
            app.SmartChargeButton.Text = {'Smart'; 'Charge'};
            app.Patterns = uitab(app.Commands);
            app.Patterns.Title = 'Patterns';
            app.PatternEdit = uibutton(app.Patterns, 'push');
            app.PatternEdit.ButtonPushedFcn = createCallbackFcn(app, @PatternEditButtonPushed, true);
            app.PatternEdit.Tooltip = {'Edit patterns and download to remote modules'};
            app.PatternEdit.Text = {'Pattern'; 'Edit'};
            app.ProfilerButton = uibutton(app.Patterns, 'push');
            app.ProfilerButton.ButtonPushedFcn = createCallbackFcn(app, @ProfilerButtonPushed, true);
            app.ProfilerButton.Tooltip = {'Profile electrodes'};
            app.ProfilerButton.Text = 'Profiler';
            app.Exercise = uibutton(app.Patterns, 'push');
            app.Exercise.ButtonPushedFcn = createCallbackFcn(app, @ExerciseButtonPushed, true);
            app.Exercise.Tooltip = {'Set exercise parameters'};
            app.Exercise.Text = 'Exercise';
            app.ActionPatternMap = uibutton(app.Patterns, 'push');
            app.ActionPatternMap.ButtonPushedFcn = createCallbackFcn(app, @ActionPatternMapButtonPushed, true);
            app.ActionPatternMap.Tooltip = {'Set exercise parameters'};
            app.ActionPatternMap.Text = {'Action to FG '; 'Pattern Map'};
            app.ActiveFGButton = uibutton(app.Patterns, 'push');
            app.ActiveFGButton.ButtonPushedFcn = createCallbackFcn(app, @ActiveFGButtonPushed, true);
            app.ActiveFGButton.Text = {'Active'; 'FG'};
            app.DisablescriptsbeforepatterneditingLabel = uilabel(app.Patterns);
            app.DisablescriptsbeforepatterneditingLabel.Text = 'Disable scripts before pattern editing';
            app.DisableScriptsButton_2 = uibutton(app.Patterns, 'push');
            app.DisableScriptsButton_2.ButtonPushedFcn = createCallbackFcn(app, @DisableScriptsButtonPushed, true);
            app.DisableScriptsButton_2.Tooltip = {'Disable scripts in the ower module'};
            app.DisableScriptsButton_2.Text = {'Disable '; 'PM Scripts'};
            app.SystemDiagramButton = uibutton(app.Patterns, 'push');
            app.SystemDiagramButton.ButtonPushedFcn = createCallbackFcn(app, @SystemDiagramButtonPushed, true);
            app.SystemDiagramButton.Tooltip = {'create or display system and subway diagrams'};
            app.SystemDiagramButton.Text = {'System'; 'Diagram'};
            app.LogTab = uitab(app.Commands);
            app.LogTab.Title = 'Log';
            app.DataLogButton = uibutton(app.LogTab, 'push');
            app.DataLogButton.ButtonPushedFcn = createCallbackFcn(app, @DataLogButtonPushed, true);
            app.DataLogButton.Tooltip = {'Read the participant data log file from the PM memory'};
            app.DataLogButton.Text = 'Data Log';
            app.DiagnosticTab = uitab(app.Commands);
            app.DiagnosticTab.Title = 'Diagnostic';
            app.NetworkScannerButton = uibutton(app.DiagnosticTab, 'push');
            app.NetworkScannerButton.ButtonPushedFcn = createCallbackFcn(app, @NetworkScannerButtonPushed, true);
            app.NetworkScannerButton.Tooltip = {'Scan the remote modules located on network under various conditions'};
            app.NetworkScannerButton.Text = {'Network'; 'Scanner'};
            app.ODTestButton = uibutton(app.DiagnosticTab, 'push');
            app.ODTestButton.ButtonPushedFcn = createCallbackFcn(app, @ODTestButtonPushed, true);
            app.ODTestButton.Tooltip = {'Object dictionary read/write, NMT commands'};
            app.ODTestButton.Text = 'OD Test';
            app.PMBootloaderButton = uibutton(app.DiagnosticTab, 'push');
            app.PMBootloaderButton.ButtonPushedFcn = createCallbackFcn(app, @PMBootloaderButtonPushed, true);
            app.PMBootloaderButton.Tooltip = {'Power module bootloader to update firmware'};
            app.PMBootloaderButton.Text = {'PM'; 'Bootloader'};
            app.RMBootloaderButton = uibutton(app.DiagnosticTab, 'push');
            app.RMBootloaderButton.ButtonPushedFcn = createCallbackFcn(app, @RMBootloaderButtonPushed, true);
            app.RMBootloaderButton.Tooltip = {'Remote module bootloader to update firmware'};
            app.RMBootloaderButton.Text = {'RM'; 'Bootloader'};
            app.CommChecklistButton = uibutton(app.DiagnosticTab, 'push');
            app.CommChecklistButton.ButtonPushedFcn = createCallbackFcn(app, @CommChecklistButtonPushed, true);
            app.CommChecklistButton.Tooltip = {'Quick list of things to check if the communications are not working with the power module'};
            app.CommChecklistButton.Text = {'Communications'; 'Checklist'};
            app.NotesButton = uibutton(app.DiagnosticTab, 'push');
            app.NotesButton.ButtonPushedFcn = createCallbackFcn(app, @NotesButtonPushed, true);
            app.NotesButton.Tooltip = {'Quick text file to keep track of changes I need to make to this app.'};
            app.NotesButton.Text = 'Notes';
            app.BTTestButton = uibutton(app.DiagnosticTab, 'push');
            app.BTTestButton.ButtonPushedFcn = createCallbackFcn(app, @BTTestButtonPushed, true);
            app.BTTestButton.Tooltip = {'Test BP module'};
            app.BTTestButton.Text = 'BP Test';
            app.MarkerSet = uibutton(app.DiagnosticTab, 'push');
            app.MarkerSet.ButtonPushedFcn = createCallbackFcn(app, @MarkerSetButtonPushed, true);
            app.MarkerSet.Tooltip = {'Read select markers in the nnps XML configuration file.'};
            app.MarkerSet.Text = {'Marker'; 'Set'};
            app.PhoneAppButton = uibutton(app.DiagnosticTab, 'push');
            app.PhoneAppButton.ButtonPushedFcn = createCallbackFcn(app, @PhoneAppButtonPushed, true);
            app.PhoneAppButton.Text = 'Phone App';
            app.getStatusButton = uibutton(app.DiagnosticTab, 'push');
            app.getStatusButton.ButtonPushedFcn = createCallbackFcn(app, @getStatusButtonPushed, true);
            app.getStatusButton.Text = 'getStatus';
            app.EMGTestDataButton = uibutton(app.DiagnosticTab, 'push');
            app.EMGTestDataButton.ButtonPushedFcn = createCallbackFcn(app, @EMGTestDataButtonPushed, true);
            app.EMGTestDataButton.Text = 'EMG Test Data';
            app.PM1F53Button = uibutton(app.DiagnosticTab, 'push');
            app.PM1F53Button.ButtonPushedFcn = createCallbackFcn(app, @PM1F53ButtonPushed, true);
            app.PM1F53Button.Text = 'PM 1F53';
            app.MessagesTextAreaLabel = uilabel(app.ClinicalSuiteUIFigure);
            app.MessagesTextAreaLabel.Text = 'Messages';
            app.MessagesTextArea = uitextarea(app.ClinicalSuiteUIFigure);
            app.ResetUSBButton = uibutton(app.ClinicalSuiteUIFigure, 'push');
            app.ResetUSBButton.ButtonPushedFcn = createCallbackFcn(app, @ResetUSBButtonPushed, true);
            app.ResetUSBButton.Tooltip = {'If USB stops responding reset without need to close nnpapi'};
            app.ResetUSBButton.Text = {'Reset'; 'USB'};
            app.PMLastErrEditField = uieditfield(app.ClinicalSuiteUIFigure, 'text');
            app.PMLastErrEditField.Editable = 'off';
            app.PMLastErrEditField.Tooltip = {'Error messages from PM will display here'};
            app.PMVersionButton = uibutton(app.ClinicalSuiteUIFigure, 'push');
            app.PMVersionButton.ButtonPushedFcn = createCallbackFcn(app, @PMVersionButtonPushed, true);
            app.PMVersionButton.Tooltip = {'Read PM version as test of communications'};
            app.PMVersionButton.Text = {'PM'; 'Version'};
            app.ClearMessageButton = uibutton(app.ClinicalSuiteUIFigure, 'push');
            app.ClearMessageButton.ButtonPushedFcn = createCallbackFcn(app, @ClearMessageButtonPushed, true);
            app.ClearMessageButton.Text = 'clear';
            app.ParticipantDropDown = uidropdown(app.ClinicalSuiteUIFigure);
            app.ParticipantDropDown.Items = {'Choose User...'};
            app.ParticipantDropDown.ValueChangedFcn = createCallbackFcn(app, @ParticipantDropDownValueChanged, true);
            app.ParticipantDropDown.Value = 'Choose User...';
            app.FullStatusButton = uibutton(app.ClinicalSuiteUIFigure, 'push');
            app.FullStatusButton.ButtonPushedFcn = createCallbackFcn(app, @FullStatusButtonPushed, true);
            app.FullStatusButton.Tooltip = {'Read PM version as test of communications'};
            app.FullStatusButton.Text = {'Full'; 'Status'};
            % Show the figure after all components are created
            app.ClinicalSuiteUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nnpClinicalSuite

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ClinicalSuiteUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ClinicalSuiteUIFigure)
        end
    end
end