classdef nnpsXmlSetsTranslate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TranslateXMLSets            matlab.ui.Figure
        SearchButtonGroup           matlab.ui.container.ButtonGroup
        GroupSetButton              matlab.ui.control.RadioButton
        ChannelSetButton            matlab.ui.control.RadioButton
        PathnameEditField           matlab.ui.control.EditField
        PathnameEditFieldLabel      matlab.ui.control.Label
        GetFileButton               matlab.ui.control.Button
        FilenameEditField           matlab.ui.control.EditField
        FilenameEditFieldLabel      matlab.ui.control.Label
        ClearButton                 matlab.ui.control.Button
        Translatedusingbase64conversionLabel  matlab.ui.control.Label
        XMLStringreadfromfileLabel  matlab.ui.control.Label
        XMLDataString               matlab.ui.control.TextArea
        Messages                    matlab.ui.control.EditField
        TranslatingLabel            matlab.ui.control.Label
        TranslatedDataString        matlab.ui.control.TextArea
        SavetoFileButton            matlab.ui.control.Button
        TranslateButton             matlab.ui.control.Button
        TranslateXMLGroupSetorMarkerSettoTextDisplayofNumbersLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        nnp = [];
        defnodes=[1:6,9]; % default nodes to scan
        XMLstring=[]; % string to write to display
        Translatestring=[]; %% string to write to display
        Outer1="";
        Outer2="";
        Inner1="";
        Inner2="";
        nnpsfilename='';
        rfid=0;
        searchset=1;
        MarkerSet=''; 
        callingFigure=[]; %handle to calling figure
    end
    
    methods (Access = private)
        
        function StrA = XMLChannelSetFcn(app,fn,Ain,ss)
            %Convert XML code from nnps file to Text and numbers
            %Ain is string, character array or cell array with groupMarketSet XML data copied from nnps file
            %if input is a cellarray of char,then need to convert to char
            
            
            if iscellstr(Ain)
                a=char(Ain);
            else
                a=Ain;
            end
            StrA=strings;%str = strings returns a string with no characters.
            % add date and time
            % dstr=datetime('now','TimeZone','local','Format','d-MMM-y HH:mm:ss');
            StrA=sprintf('%s\n',fn);
            pairtext='groupSet_ID';
            psize=size(pairtext,2)+2;
            pair1=['<',pairtext,'>'];
            pair2=['</',pairtext,'>'];
            b=regexpi(a,pair1);
            c=regexpi(a,pair2);
            TotalMarkers=size(b,2);
            %T = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames)
            Vtype={'double','string'};
            
                Vname={'groupSet_Id','translated'};

            Tsize=[TotalMarkers,2];
            ChannelSet=table('Size',Tsize,'VariableTypes',Vtype,'VariableNames',Vname);
            %
            FGID=zeros(TotalMarkers,1);
            for i=1:TotalMarkers
                FGid=str2double((extractBetween(a,b(i)+psize,c(i)-1)));
                ChannelSet.groupSet_Id(i)=FGid;
            end
            % I could make this into a function with ID and type but will just do both
            % sets for now.
            if ss==1
                pairtext='ChannelData';
            else
                pairtext='groupPatternMarkerSet';
            end
            psize=size(pairtext,2)+2;
            pair1=['<',pairtext,'>'];
            pair2=['</',pairtext,'>'];
            b=regexpi(a,pair1);
            c=regexpi(a,pair2);
            TotalMarkers=size(b,2);
            for i=1:TotalMarkers
                d64=extractBetween(a,b(i)+psize,c(i)-1);
                ChannelSet.translated(i)={d64};
            end
            
            for i=1:TotalMarkers
                glength(i)=length(char(ChannelSet{i,2}));
            end
            maxlength=max(glength)+1;
            
            for i=1:TotalMarkers
                mflag='  ';
                base64str=char(ChannelSet{i,2});
                FG=ChannelSet{i,1};
                b64length=length(base64str);
                newspaces=blanks(maxlength-b64length);
                commandrow=base64decodeX(base64str);
                commandrowsize=size(commandrow,2);
                if ss==1
                    maxcommand=commandrow(1,commandrowsize-2);
                else
                    maxcommand=commandrow(1,commandrowsize);
                end
                if maxcommand<255
                    mflag='**';
                end
                StrA=[StrA sprintf('%s FG %2d, Max:%d  %s %s ',mflag,FG,maxcommand,base64str,newspaces)];
                StrA=[StrA sprintf('%d ',commandrow)];
                %     StrA=[StrA sprintf('\n')];
                StrA=[StrA newline];
            end
        end
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin>1
                app.callingFigure=varargin{1};
            end

            app.TranslatingLabel.Visible='off';
            app.XMLDataString.Value='';
            app.XMLstring="";
            app.Translatestring="";
            app.TranslatedDataString.Value='';
            app.Outer1=sprintf('<eachChannel>\n');
            app.Outer2=sprintf('</eachChannel>\n');
            app.Inner1="<ChannelSet>";
            app.Inner2="</ChannelSet>";
            
        end

        % Button pushed function: TranslateButton
        function TranslateButtonPushed(app, event)
            app.XMLstring=' '; %first clear the xmlstring
            app.rfid=fopen(app.nnpsfilename);
            app.Messages.Value='';
            app.TranslatingLabel.Visible='on';
            % firs want to exract the xml segment into the XMLstring
            app.XMLstring=strcat(app.XMLstring,app.Outer1);
            fstatus=false;
            while ~fstatus
                tline=fgets(app.rfid);
                CS=regexpi(tline,app.Inner1);
                if ~isempty(CS)
                    
                    % here I need to keep copying lines until CSE=~isempty
                    inCS=true;
                    while inCS
                        tline=fgets(app.rfid);
                        CSE=regexpi(tline,app.Inner2); %end of channelset
                        %fprintf(wfid,'%s',tline);  % print regardless
                        app.XMLstring=strcat(app.XMLstring,sprintf('%s',tline));
                        inCS=isempty(CSE); % will not be empty when you hit end of scriptrow
                        if feof(app.rfid) %if reach eof before finding end of SR then break
                            break
                        end
                    end
                    
                end
                fstatus=feof(app.rfid);
            end
            
            app.XMLstring=strcat(app.XMLstring,app.Outer2);
            fclose(app.rfid);
            
            app.XMLDataString.Value=app.XMLstring;
            app.TranslatedDataString.Value = app.XMLChannelSetFcn(app.FilenameEditField.Value,app.XMLstring,app.searchset); % Will edit this to include flag for which button active
            app.MarkerSet=app.TranslatedDataString.Value{1}; % need {} because value is a cell
            app.TranslatingLabel.Visible='off';
            
        end

        % Button pushed function: SavetoFileButton
        function SavetoFileButtonPushed(app, event)
            % Get file name and write string to file.         
            timestring=sprintf('_%02.0f',datevec(now));
            [mpn,mfn,~]=fileparts(app.nnpsfilename);
            defaultname=fullfile(mpn,['MarkerSet_',mfn,'.txt']);
            [fn,pn]=uiputfile('*.txt','Specify Filename and location for output text file',defaultname);
            savename=fullfile(pn,fn);
            SaveID=fopen(savename,'a'); %open in append mode in case I select the wrong file
            fprintf(SaveID,'%s',app.MarkerSet);
            fclose(SaveID);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.XMLDataString.Value='';
            app.MarkerSet='';
            app.TranslatedDataString.Value='';
        end

        % Button pushed function: GetFileButton
        function GetFileButtonPushed(app, event)
         if isempty(app.PathnameEditField.Value)
            [fn, pn]=uigetfile('*.nnps','NNPS file to open');
         else
             [fn, pn]=uigetfile('*.nnps','NNPS file to open',app.PathnameEditField.Value);
         end
            app.nnpsfilename=fullfile(pn,fn); 
            app.FilenameEditField.Value=fn;
            app.PathnameEditField.Value=pn;
            figure(app.TranslateXMLSets); %return focus to figure after getting file
        end

        % Selection changed function: SearchButtonGroup
        function SearchButtonGroupSelectionChanged(app, event)
            selectedButton = app.SearchButtonGroup.SelectedObject;
            if app.ChannelSetButton.Value
                app.Outer1=sprintf('<eachChannel>\n');
                app.Outer2=sprintf('</eachChannel>\n');
                app.Inner1="<ChannelSet>";
                app.Inner2="</ChannelSet>";
                app.searchset=1;
            end
            if app.GroupSetButton.Value
                app.Outer1=sprintf('<nnpData>\n');
                app.Outer2=sprintf('</nnpData>\n');
                app.Inner1="<GroupSet>";
                app.Inner2="</GroupSet>";
                app.searchset=2;
            end
        end

        % Close request function: TranslateXMLSets
        function TranslateXMLSetsCloseRequest(app, event)
            if ishandle(app.callingFigure) % check that is is valid
                figure(app.callingFigure); % bring to front
            end
            
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TranslateXMLSets = uifigure('Visible', 'off');
            app.TranslateXMLSets.Name = 'TranslateXMLSets';
            app.TranslateXMLSets.CloseRequestFcn = createCallbackFcn(app, @TranslateXMLSetsCloseRequest, true);
            app.TranslateXMLGroupSetorMarkerSettoTextDisplayofNumbersLabel = uilabel(app.TranslateXMLSets);
            app.TranslateXMLGroupSetorMarkerSettoTextDisplayofNumbersLabel.Text = 'Translate XML GroupSet or MarkerSet to Text Display of Numbers';
            app.TranslateButton = uibutton(app.TranslateXMLSets, 'push');
            app.TranslateButton.ButtonPushedFcn = createCallbackFcn(app, @TranslateButtonPushed, true);
            app.TranslateButton.Text = 'Translate';
            app.SavetoFileButton = uibutton(app.TranslateXMLSets, 'push');
            app.SavetoFileButton.ButtonPushedFcn = createCallbackFcn(app, @SavetoFileButtonPushed, true);
            app.SavetoFileButton.Text = {'Save '; 'to File'};
            app.TranslatedDataString = uitextarea(app.TranslateXMLSets);
            app.TranslatingLabel = uilabel(app.TranslateXMLSets);
            app.TranslatingLabel.Text = 'Translating ...';
            app.Messages = uieditfield(app.TranslateXMLSets, 'text');
            app.XMLDataString = uitextarea(app.TranslateXMLSets);
            app.XMLStringreadfromfileLabel = uilabel(app.TranslateXMLSets);
            app.XMLStringreadfromfileLabel.Text = 'XML String read from file';
            app.Translatedusingbase64conversionLabel = uilabel(app.TranslateXMLSets);
            app.Translatedusingbase64conversionLabel.Text = 'Translated using base64 conversion';
            app.ClearButton = uibutton(app.TranslateXMLSets, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.FilenameEditFieldLabel = uilabel(app.TranslateXMLSets);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.TranslateXMLSets, 'text');
            app.GetFileButton = uibutton(app.TranslateXMLSets, 'push');
            app.GetFileButton.ButtonPushedFcn = createCallbackFcn(app, @GetFileButtonPushed, true);
            app.GetFileButton.Tooltip = {'Choose NNPS file'};
            app.GetFileButton.Text = 'Get File';
            app.PathnameEditFieldLabel = uilabel(app.TranslateXMLSets);
            app.PathnameEditFieldLabel.Text = 'Pathname';
            app.PathnameEditField = uieditfield(app.TranslateXMLSets, 'text');
            app.SearchButtonGroup = uibuttongroup(app.TranslateXMLSets);
            app.SearchButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SearchButtonGroupSelectionChanged, true);
            app.SearchButtonGroup.Title = 'Search';
            app.ChannelSetButton = uiradiobutton(app.SearchButtonGroup);
            app.ChannelSetButton.Text = 'ChannelSet';
            app.ChannelSetButton.Value = true;
            app.GroupSetButton = uiradiobutton(app.SearchButtonGroup);
            app.GroupSetButton.Text = 'GroupSet';
            % Show the figure after all components are created
            app.TranslateXMLSets.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nnpsXmlSetsTranslate(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TranslateXMLSets)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TranslateXMLSets)
        end
    end
end