classdef nnpsurgerysuite < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ScriptsPanel               matlab.ui.container.Panel
        DisableScriptsButton       matlab.ui.control.Button
        ClearMessageButton         matlab.ui.control.Button
        AccessPointPanel           matlab.ui.container.Panel
        StatusButton               matlab.ui.control.Button
        RadioButton                matlab.ui.control.Button
        NNPAccessPointButton       matlab.ui.control.StateButton
        PowerNetworkPanel          matlab.ui.container.Panel
        VnetUpdatePanel            matlab.ui.container.Panel
        PMVNETEditField            matlab.ui.control.NumericEditField
        UpdateButton               matlab.ui.control.Button
        NetworkONButton            matlab.ui.control.Button
        NetworkOFFButton_3         matlab.ui.control.Button
        StatusPanel                matlab.ui.container.Panel
        RSSIEditField              matlab.ui.control.NumericEditField
        RSSIEditFieldLabel         matlab.ui.control.Label
        VnetEditField              matlab.ui.control.NumericEditField
        VnetEditFieldLabel         matlab.ui.control.Label
        VbatteryEditField          matlab.ui.control.NumericEditField
        VbatteryEditFieldLabel     matlab.ui.control.Label
        PMTempEditField            matlab.ui.control.NumericEditField
        PMTempEditFieldLabel       matlab.ui.control.Label
        RefreshButton              matlab.ui.control.Button
        NetworkLamp                matlab.ui.control.Lamp
        NetworkLampLabel           matlab.ui.control.Label
        PMShutdownPanel            matlab.ui.container.Panel
        AllowShutdownCheckBox      matlab.ui.control.CheckBox
        PMShutdownButton           matlab.ui.control.Button
        MessagesTextArea           matlab.ui.control.TextArea
        MessagesTextAreaLabel      matlab.ui.control.Label
        TabGroup                   matlab.ui.container.TabGroup
        NodeNumberTab              matlab.ui.container.Tab
        NodeConfigurePanel         matlab.ui.container.Panel
        BootRevEditField           matlab.ui.control.EditField
        BootRevEditFieldLabel      matlab.ui.control.Label
        ModuleTypeEditField        matlab.ui.control.EditField
        ModuleTypeEditFieldLabel   matlab.ui.control.Label
        SerialEditField            matlab.ui.control.NumericEditField
        SerialEditFieldLabel       matlab.ui.control.Label
        NodeScanListTextArea       matlab.ui.control.TextArea
        NodeScanListTextAreaLabel  matlab.ui.control.Label
        NodeEditField              matlab.ui.control.NumericEditField
        NodeEditFieldLabel         matlab.ui.control.Label
        ChangeNodeButton           matlab.ui.control.Button
        SelectNodeButton           matlab.ui.control.StateButton
        PowerModuleSettingsPanel   matlab.ui.container.Panel
        NetworkOFFButton_2         matlab.ui.control.Button
        NetworkBOOTLOADERButton    matlab.ui.control.Button
        PMAppRevisionEditField     matlab.ui.control.NumericEditField
        AppRevEditFieldLabel       matlab.ui.control.Label
        PMPCBSerialEditField       matlab.ui.control.NumericEditField
        SerialEditField_2Label     matlab.ui.control.Label
        ScanModeButtonGroup        matlab.ui.container.ButtonGroup
        ListedNodesButton          matlab.ui.control.RadioButton
        AllNodesButton             matlab.ui.control.RadioButton
        ListBox                    matlab.ui.control.ListBox
        ScanforNodesButton         matlab.ui.control.Button
        StimulationTab             matlab.ui.container.Tab
        StimulatePanel             matlab.ui.container.Panel
        PWincEditField_2           matlab.ui.control.NumericEditField
        IncrementLabel_2           matlab.ui.control.Label
        PWuSLabel                  matlab.ui.control.Label
        PAmALabel                  matlab.ui.control.Label
        PWUpButton                 matlab.ui.control.Button
        PWDnButton                 matlab.ui.control.Button
        PWEditField                matlab.ui.control.NumericEditField
        PWincEditField             matlab.ui.control.NumericEditField
        IncrementLabel             matlab.ui.control.Label
        NodeEditField_2            matlab.ui.control.NumericEditField
        NodeEditField_2Label       matlab.ui.control.Label
        ChanEditField              matlab.ui.control.NumericEditField
        ChanEditFieldLabel         matlab.ui.control.Label
        ZeroPWButton               matlab.ui.control.Button
        PADnButton                 matlab.ui.control.Button
        PAUpButton                 matlab.ui.control.Button
        PAEditField                matlab.ui.control.NumericEditField
        StimOffButton              matlab.ui.control.Button
        StimOnButton               matlab.ui.control.Button
        NextNodeButton             matlab.ui.control.Button
        PrevNodeButton             matlab.ui.control.Button
        NextChButton               matlab.ui.control.Button
        PrevChButton               matlab.ui.control.Button
        MiscTab                    matlab.ui.container.Tab
        PMBootloaderButton         matlab.ui.control.Button
        ScriptsPanel_2             matlab.ui.container.Panel
        StartupScriptButton        matlab.ui.control.Button
        ConfigAPButton_5           matlab.ui.control.Button
        ConfigAPButton_4           matlab.ui.control.Button
        ODAccessButton             matlab.ui.control.StateButton
        NetworkScannerButton       matlab.ui.control.StateButton
        NNPSurgeryToolLabel        matlab.ui.control.Label
    end

    
    % Notes
    % This is the Matlab interface created to be used in the Operating Room
    % during an implant of an NNP system.
    % It is a simplistic version of the nnpClinicianSuite
    % soley for therapists or clinical engineers to program and edit 
    % the newly implanted stim amd EMG electrodes and modules during a
    % surgery.
    
    properties (Access = private)
    end
    
    properties (Access = public)
        nnp = []; % Description
        enableToolTips = false;
        mainDir=[];
        oldPath=[];

        stimOn = false; 
    end
    
    methods (Access = private)
         
        function ResponseMessage(app,good,message)
            if good
                dmessage=message;
                tbc=[1,1,1];
            else
                dmessage=app.nnp.lastError;
                tbc=[1,1,.7];
            end
            app.MessagesTextArea.Value=dmessage;
            app.MessagesTextArea.BackgroundColor=tbc;
        end
 
        function handleErrors(app, err, title)
            switch err
                case 0 %don't do anything
                case 1, msgbox('PM internal or CAN error (node may not be on network)', title, 'error');
                case 2, msgbox('PM response is too short', title, 'error');
                case 3, msgbox('Radio Timeout', title, 'error');
                case 4, msgbox('Unknown Response from Access Point', title, 'error');
                case 5, msgbox('No response from Access Point', title, 'error');
                case 6, msgbox('PM response does not echo request', title, 'error');
                case 7, msgbox('Bad CRC', title, 'error');
                otherwise msgbox('Unknown error', title, 'error');
            end
        end
        
        function TopMessage(app,message)
             app.MessagesTextArea.Value=vertcat(message, string(app.MessagesTextArea.Value)); % put new message on top
        end
        
        
        function ClearMessages(app)
            app.MessagesTextArea.Value=''; % clear messages
        end

        function getNNP_Status(app)
            % Checks for status of PM and modes of connected modules
            % plan to use one string for all
             str=''; %initialize string
          
            resp=double(app.nnp.read(7,'1018',3,'uint32')); % read revision number to test communications
            %app.ResponseMessage(~isempty(resp),sprintf('PM version %d',resp));
            if ~isempty(resp)
                pmStr=sprintf('\nPM version %d\n',resp);
                str=[str, pmStr];
            else
                app.MessagesTextArea.Value="Error getting PM version";
            end
            % Next get Status with getStatus

             %  [modes, temp, vsys, net, rssi, lqi, group, lpm] = getStatus(NNP, nodes)
         % Could look only at nodes 1:9 for nodes
             nodes2scan=uint8(1:9);
            [modes, temp, vsys, net, rssi] = app.nnp.getStatus(nodes2scan);
            if ~isempty(modes)
                if net
                    str =  [str,sprintf('Network is ON')];
                    vnet = double(app.nnp.read(7, '3010', 0, 'uint8'))/10; %VNET
                    str =  [str,sprintf('\nNetwork Voltage: %4.1fV',vnet)];
                else
                    str = [str, sprintf('Network is OFF')];
                end
                str = [str, sprintf('\n\nPM Temp: %4.1f⁰C\nRSSI: %4.1fdBm\nVSYS: %4.1fV\nPM mode: %s\n\n', temp, rssi, vsys, modes{7})];
                %insert here a read of battery voltages from 3000.0D
                bat=double(app.nnp.read(7,'3000',13,'uint8')); % read revision number to test communications
                if length(bat)<20

                else
                    bat_V1 = (bat( 7)+bat( 8)*256)/1000; 
                    bat_V2 = (bat( 9)+bat(10)*256)/1000; 
                    bat_V3 = (bat(11)+bat(12)*256)/1000; %in V
   
                    str = [str, sprintf('Vbatt: %4.2f,  %4.2f,  %4.2f\n\n',bat_V1,bat_V2,bat_V3)];
                end

                systemControl = app.nnp.read(7,'2001',1, 'uint32');
                if length(systemControl)==1
                    scriptsEnabled = bitget(systemControl,5);
                    if scriptsEnabled == 1
                        str = [str,sprintf('Scripts are enabled \n')];
                    else
                        str = [str,sprintf('Scripts are disabled \n')];
                    end
                else
                end

                if net
                    str = [str, sprintf('Note: Nodes not listed on network may be in bootloader mode.\nNodes also may take up to 2s to update.\n\n')];
                    for i=[1:6 8:length(modes)]
                        % here I could look at modes{i} to see if
                        % strcomp='Patient' to determine whether to scan
                        str=[str, sprintf('Node %2d: %s\n', i, modes{i})];
                    end
                else
                    str = [str,'Note: Nodes not listed because network is off'];
                end
        uialert(app.UIFigure, str, 'Status', 'Icon', 'info');
            end
        end

        function enableNodeTabSelectionsAll(app, enable)
            %enables all Node Number section buttons
            app.BootRevEditField.Enable = enable;
            app.BootRevEditFieldLabel.Enable = enable;
            app.ModuleTypeEditField.Enable = enable;
            app.ModuleTypeEditFieldLabel.Enable = enable;
            app.SerialEditField.Enable = enable;
            app.SerialEditFieldLabel.Enable = enable;
            app.NodeScanListTextArea.Enable = enable;
            app.NodeScanListTextAreaLabel.Enable = enable;
            app.NodeEditField.Enable = enable;
            app.NodeEditFieldLabel.Enable = enable;
            app.ChangeNodeButton.Enable = enable;
            app.SelectNodeButton.Enable = enable;
            app.PowerModuleSettingsPanel.Enable = enable;
            app.NetworkOFFButton_2.Enable = enable;
            app.NetworkBOOTLOADERButton.Enable = enable;
            app.PMAppRevisionEditField.Enable = enable;
            app.PMPCBSerialEditField.Enable = enable;
            app.ScanModeButtonGroup.Enable = enable;
            app.ListedNodesButton.Enable = enable;
            app.AllNodesButton.Enable = enable;
            app.ListBox.Enable = enable;
            app.ScanforNodesButton.Enable = enable;
        end
    
        function enableStimTabSelectionsAll(app, enable)
            %enables all Node Stimulation section buttons
            app.PWincEditField_2.Enable = enable;
            app.PWuSLabel.Enable = enable;
            app.PAmALabel.Enable = enable;
            app.PWUpButton.Enable = enable;
            app.PWDnButton.Enable = enable;
            app.PWEditField.Enable = enable;
            app.PWincEditField.Enable = enable;
            app.NodeEditField_2.Enable = enable;
            app.ChanEditField.Enable = enable;
            app.ZeroPWButton.Enable = enable;
            app.PADnButton.Enable = enable;
            app.PAUpButton.Enable = enable;
            app.PAEditField.Enable = enable;
            app.StimOffButton.Enable = enable;
            app.StimOnButton.Enable = enable;
            app.NextNodeButton.Enable = enable;
            app.PrevNodeButton.Enable = enable;
            app.NextChButton.Enable = enable;
            app.PrevChButton.Enable = enable;
        end

         function enableAll(app, enable)
          
            app.AllNodesButton.Enable = enable;
            app.ListedNodesButton.Enable = enable;

            if app.AllNodesButton.Value == 1
                app.NodeScanListTextArea.Enable = 'off';
            else
                app.NodeScanListTextArea.Enable = enable;
            end
            if app.SelectNodeButton.Value == 1
                app.SerialEditField.Enable = 'off';
                app.ChangeNodeButton.Enable = enable;
                app.ModuleTypeEditField.Enable = enable;
                app.BootRevEditField.Enable = enable;
                app.NodeEditField.Enable = 'off';
                app.ListBox.Enable = 'off';
            else
                app.ChangeNodeButton.Enable = 'off';
                app.SerialEditField.Enable = 'on';
                app.ModuleTypeEditField.Enable = 'off';
                app.BootRevEditField.Enable = 'off';
                app.NodeEditField.Enable = 'on';
                app.ListBox.Enable = enable;
            end
         end


        function DeselectNode(app)
             app.SelectNodeButton.Value = 0;
             app.SelectNodeButton.Text = 'Select Node';
             app.SerialEditField.Value = 0;
             app.ModuleTypeEditField.Value = '';
             app.BootRevEditField.Value = '';
             app.ListBox.Items = {};
             app.enableAll('on');
        end
        
        function SelectNode(app)
             app.SelectNodeButton.Value = 1;
             app.SelectNodeButton.Text = 'Node Selected. Press to deselect';
             app.ListBox.Items = {};
             %app.RestoreODDefaultsCheckBox.Enable = 'on';
        end
    

        function resetStim(app, varargin)
            if nargin == 2
                forceOff = varargin{1};
            else
                forceOff = false;
            end
                
            
            %if the PW and PA are both non zero, turn off Stim, before swithcing channels.  
            % Otherwise, leave everything as is.  If stim is turned off, highlight the non-zero
            % values so operator is reminded to set them appropriately before turning stim back on
            % use Force off between nodes
            if app.stimOn && (forceOff || (app.PWEditField.Value ~= 0 && app.PAEditField.Value ~= 0)) 
                StimOFFButtonPushed(app, 0);
                
                %Highlight the non-zero values
                if app.PWEditField.Value ~= 0
                    app.PWEditField.BackgroundColor = [1, 1, 0];
                end
                if app.PAEditField.Value ~= 0
                    app.PAEditField.BackgroundColor = [1, 1, 0];
                end
            end
        end
       
    
        function error = setStim(app)
            app.PWEditField.BackgroundColor = [1, 1, 1];
            app.PAEditField.BackgroundColor = [1, 1, 1];
            
            node = app.NodeEditField_2.Value;
            ch = app.ChanEditField.Value;
            pw = app.PWEditField.Value;
            pa = app.PAEditField.Value;
            
            result = app.nnp.write(node, '3212', ch, uint8([pw pa*10]));
            if isequal(result, 0)
               error = 0;
            else
                error = 1;
                %indicate that values may not have updated
                app.PWEditField.BackgroundColor = [1, 0, 0];
                app.PAEditField.BackgroundColor = [1, 0, 0];
                app.TopMessage(sprintf(['The Pulse Width/Amplitude may not have updated correctly\n'...
                                    'Error:' app.nnp.lastError]));
            end
            
           end


    end %ends private functions


    methods (Access = public)
        
        function returnFocus(app)
           % drawnow;
           figure(app.UIFigure); 
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: NNPAccessPointButton
        function NNPAccessPointButtonValueChanged(app, event)
            value = app.NNPAccessPointButton.Value;
            if ~isempty(app.nnp)
                if isvalid(app.nnp) && ~isempty(app.nnp.port)
                    app.StimOffButtonPushed();
                end
                delete(app.nnp)
            end
            
            if app.NNPAccessPointButton.Value
                app.NNPAccessPointButton.Text = 'NNP Access Point-Select Port';
                app.NNPAccessPointButton.BackgroundColor=[.9,.9,.9];
                while true
                    app.TopMessage('Opening...');
                    app.nnp = NNPAPI();
                    app.returnFocus(); 
                    if isempty(app.nnp.port)
                        resp = questdlg('No port chosen for NNP Access Point.  Plug in AccessPoint and try again.  If you do not want to try again, press No and the application will close');
                        if isequal(resp, 'No')
                            %close(app.fillintheblank); % if I want to close something
                            return;
                        end
                    else                        
                        break;
                    end
                end
                %app.nnp.timeout=1;  % set new default timeout that matches 5 retries
                app.nnp.timeout=.5;  % set new default timeout that matches 2 retries (total of 3 tries) at 100ms radio +23ms for WOR
                % how long Matlab waits for usb response - may be some
                % jitter.
                app.NNPAccessPointButton.Text = 'NNP Access Point Open';
                app.returnFocus();    
                app.NNPAccessPointButton.BackgroundColor=[0.81,1.00,0.67];
                
                app.RefreshButtonPushed(); %Refresh status box

                set(app.RefreshButton,'Enable','on');
                set(app.NetworkLamp,'Enable','on');
                set(app.NetworkLampLabel,'Enable','on');
                set(app.RSSIEditField,'Enable','on');
                set(app.RSSIEditFieldLabel,'Enable','on');
                set(app.VbatteryEditField,'Enable','on');
                set(app.VbatteryEditFieldLabel,'Enable','on');
                set(app.PMTempEditField,'Enable','on');
                set(app.PMTempEditFieldLabel,'Enable','on');
                set(app.VnetEditField,'Enable','on');
                set(app.VnetEditFieldLabel,'Enable','on');

                set(app.ScriptsPanel,'Enable','on');
                set(app.DisableScriptsButton,'Enable','on');
                set(app.StimulatePanel,'Enable','on');
                
                set(app.PMVNETEditField,'Enable','on');
                set(app.UpdateButton,'Enable','on');
                
                set(app.NetworkOFFButton_3, 'Enable', 'on');
                set(app.NetworkONButton, 'Enable', 'on');
                
                set(app.StatusButton, 'Enable', 'on');
                set(app.RadioButton, 'Enable', 'on');

                set(app.AllowShutdownCheckBox, 'Enable', 'on');

                %Misc. Tab Buttons
                set(app.ODAccessButton, 'Enable', 'on');
                set(app.NetworkScannerButton, 'Enable', 'on');
                set(app.ConfigAPButton_4, 'Enable', 'on');
                set(app.ConfigAPButton_5, 'Enable', 'on');
                set(app.StartupScriptButton, 'Enable', 'on');
                 set(app.PMBootloaderButton, 'Enable', 'on');
                
                app.TopMessage('Finished opening Access Point');

                app.enableNodeTabSelectionsAll('on'); %enables all Node Number section buttons for start
                app.enableStimTabSelectionsAll('on');%enables all Stimulation section buttons

                %Below 5 if cases retreive data necessary for the Node selection:
                %Get Access Point SW/HW Revision
                resp = app.nnp.getAPRev();  
                if length(resp) ~= 1 || resp(1) < 3
                    msgbox('Access Point must be at Rev 3 or higher');
                    return;
                end
    
    
                 %Get PM Serial number
                resp = app.nnp.getSerial(7);  
                if length(resp)==1
                    app.PMPCBSerialEditField.Value = resp(1);
                    app.PMPCBSerialEditField.BackgroundColor = [1 1 1];
                else
                    app.PMPCBSerialEditField.Value = Inf; 
                    app.PMPCBSerialEditField.BackgroundColor = [1 0 0];
                end
                
                %Get SW Rev
                resp = app.nnp.getSwRev(7);  
                if length(resp)==1
                    app.PMAppRevisionEditField.Value = resp(1);
                    app.PMAppRevisionEditField.BackgroundColor = [1 1 1];
                else
                    app.PMAppRevisionEditField.Value = Inf; 
                    app.PMAppRevisionEditField.BackgroundColor = [1 0 0];
                end
            else
                app.NNPAccessPointButton.Text = 'NNP Access Point Closed';
                app.returnFocus();
                app.NNPAccessPointButton.BackgroundColor=[.96,.96,.96];
                
                set(app.RefreshButton,'Enable','off');
                set(app.NetworkLamp,'Enable','off');
                set(app.NetworkLampLabel,'Enable','off');
                set(app.RSSIEditField,'Enable','off');
                set(app.RSSIEditFieldLabel,'Enable','off');
                set(app.VbatteryEditField,'Enable','off');
                set(app.VbatteryEditFieldLabel,'Enable','off');
                set(app.PMTempEditField,'Enable','off');
                set(app.PMTempEditFieldLabel,'Enable','off');
                set(app.VnetEditField,'Enable','off');
                set(app.VnetEditFieldLabel,'Enable','off');

                set(app.ScriptsPanel,'Enable','off');
                set(app.DisableScriptsButton,'Enable','off');
                
                set(app.PMVNETEditField,'Enable','off');
                set(app.UpdateButton,'Enable','off');
                
                set(app.NetworkOFFButton_3, 'Enable', 'off');
                set(app.NetworkONButton, 'Enable', 'off');
                
                set(app.StatusButton, 'Enable', 'off');
                set(app.RadioButton, 'Enable', 'off');

                set(app.AllowShutdownCheckBox, 'Enable', 'off');

                %Misc. Tab Buttons
                set(app.ODAccessButton, 'Enable', 'off');
                set(app.NetworkScannerButton, 'Enable', 'off');
                set(app.ConfigAPButton_4, 'Enable', 'off');
                set(app.ConfigAPButton_5, 'Enable', 'off');
                set(app.StartupScriptButton, 'Enable', 'off');
                set(app.PMBootloaderButton, 'Enable', 'off');

                app.enableNodeTabSelectionsAll('off'); %disables all Node Number section buttons
                app.enableStimTabSelectionsAll('off');%disables all Stimulation section buttons

                app.TopMessage('Finished Closing Access Point');
            end
        
        end

        % Button pushed function: RadioButton
        function RadioButtonPushed(app, event)
            radioconfig(app.nnp,app.UIFigure);
        end

        % Button pushed function: NetworkONButton
        function NetworkONButtonPushed(app, event)
            if app.nnp.enterWaiting()
                if app.nnp.networkOn()
                    dmessage = 'Network On';
                    tbc=[1,1,1];
                    app.NetworkLamp.Color = 'green';
                else
                    dmessage=app.nnp.lastError;
                    tbc=[1,1,.7];
                    app.NetworkLamp.Color = [0.7,0.7,0.7];
                end
            else
                dmessage=app.nnp.lastError;
                tbc=[1,1,.7];
            end
            app.MessagesTextArea.Value=dmessage;
            app.MessagesTextArea.BackgroundColor=tbc;
            
        end

        % Button pushed function: NetworkOFFButton_3
        function NetworkOFFButton_3Pushed(app, event)
            if app.nnp.enterWaiting()
                if app.nnp.networkOff()
                    dmessage = 'Network Off';
                     tbc=[1,1,1];
                     app.NetworkLamp.Color = 'red';
                     app.DeselectNode();
                else
                    dmessage=app.nnp.lastError;
                    tbc=[1,1,.7];
                    app.NetworkLamp.Color = [0.7,0.7,0.7];
                end
            else
                dmessage=app.nnp.lastError;
                    tbc=[1,1,.7];
            end
            app.MessagesTextArea.Value=dmessage;
            app.MessagesTextArea.BackgroundColor=tbc;


        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
               %Network Status
           networkStatus = double(app.nnp.read(7, '3004', 1));

           if networkStatus == 1  %network is on
                app.NetworkLamp.Color = 'green';
           elseif isempty(networkStatus)
               app.TopMessage('There is no radio signal or communication error');
               resp = uiconfirm(app.UIFigure, "There is no radio signal or communication error", "PM Connect Failed", "Options", ["Ok", "Open Radio Config", "Close"], "DefaultOption", 2);
               if(resp == "Open Radio Config")
                   radioconfig(app.nnp)
               end
               app.RSSIEditField.Value = 0;
               return
           else
               app.NetworkLamp.Color = 'red';
               app.DeselectNode();
           end

           %Retrieving Radio Signal Strength (RSSI)
           signal = app.nnp.RSSI;
           app.RSSIEditField.Value = signal;

           if signal < -75
                app.RSSIEditField.BackgroundColor = [1.00,0.00,0.00]; %red
           elseif (signal <= -72) && (signal > -75 )
               app.RSSIEditField.BackgroundColor = [1.00,1.00,0.00]; %yellow
           else
               app.RSSIEditField.BackgroundColor = [1.00,1.00,1.00]; %white
           end

            %Retrieving PM Temperature
            tempRead = app.nnp.read(7, '2003', 1, 'uint16'); 
            if length(tempRead) == 1
                temp = double(tempRead)/10;
            else
                temp = [];
                app.TopMessage('Error Reading the Power Module Temperature');
            end
           
            if ~isempty(temp)
                 app.PMTempEditField.Value = temp;
                 if temp >= 39.0
                     app.PMTempEditField.BackgroundColor = [1.00,0.00,0.00];
                 elseif (temp >= 38.0) && (temp < 39.0)
                     app.PMTempEditField.BackgroundColor = [1.00,1.00,0.00];
                 else
                     app.PMTempEditField.BackgroundColor = [1.00,1.00,1.00];
                 end
            end
             
            %Retrieving Battery Voltage of lowest battery of the 3
            bat=double(app.nnp.read(7,'3000',13,'uint8')); % read revision number to test communications
            
            if length(bat)<20
%                 batV1 = (bat( 7)+bat( 8)*256)/1000; %in Voltage
%                 batV2 = (bat( 9)+bat(10)*256)/1000; %in Voltage
%                 batV3 = (bat(11)+bat(12)*256)/1000; %in Voltage
                app.TopMessage('Error Charging Data Packet was not received successfully');
            else
                batV1 = (bat( 7)+bat( 8)*256)/1000; %in Voltage
                batV2 = (bat( 9)+bat(10)*256)/1000; %in Voltage
                batV3 = (bat(11)+bat(12)*256)/1000; %in Voltage

                %Find the battery with the lowest voltage
                min_batteryVolt = min([batV1,batV2,batV3]);
                roundedMinBatt = round(min_batteryVolt, 1); %rounds temp up to nearest tenth
                app.VbatteryEditField.Value = roundedMinBatt;

                    if roundedMinBatt == 3.2
                        app.PMShutdownButtonPushed()
                    elseif (roundedMinBatt > 3.2) && (roundedMinBatt <= 3.3)
                        app.VbatteryEditField.BackgroundColor = [1.00,0.00,0.00];
                    elseif (roundedMinBatt > 3.3) && (roundedMinBatt <= 3.5)
                        app.VbatteryEditField.BackgroundColor = [1.00,0.00,0.00];
                    elseif (roundedMinBatt > 3.5) && (roundedMinBatt <= 3.7)
                        app.VbatteryEditField.BackgroundColor = [1.00,1.00,0.00];
                    else
                        app.VbatteryEditField.BackgroundColor = [1.00,1.00,1.00];
                    end

            end

            %Retrieving Network Voltage
            vnet = double(app.nnp.read(7, '3010', 0, 'uint8'))/10; %VNET
            if ~isempty(vnet)
                app.VnetEditField.Value = vnet;
            else
                vnet = [];
                app.TopMessage('Error Reading the Network Voltage');
            end

            %Make sure PM Vlaues are updated for Node Selection Process
            %Get PM Serial number
                resp = app.nnp.getSerial(7);  
                if length(resp)==1
                    app.PMPCBSerialEditField.Value = resp(1);
                    app.PMPCBSerialEditField.BackgroundColor = [1 1 1];
                else
                    app.PMPCBSerialEditField.Value = Inf; 
                    app.PMPCBSerialEditField.BackgroundColor = [1 0 0];
                end
                
                %Get SW Rev
                resp = app.nnp.getSwRev(7);  
                if length(resp)==1
                    app.PMAppRevisionEditField.Value = resp(1);
                    app.PMAppRevisionEditField.BackgroundColor = [1 1 1];
                else
                    app.PMAppRevisionEditField.Value = Inf; 
                    app.PMAppRevisionEditField.BackgroundColor = [1 0 0];
                end
                
        end

        % Button pushed function: ClearMessageButton
        function ClearMessageButtonPushed(app, event)
            app.ClearMessages();
        end

        % Value changed function: AllowShutdownCheckBox
        function AllowShutdownCheckBoxValueChanged(app, event)
            value = app.AllowShutdownCheckBox.Value;
            if value == 1
            set(app.PMShutdownButton, 'Enable', 'on');
            else
            set(app.PMShutdownButton, 'Enable', 'off');
            end
        end

        % Button pushed function: PMShutdownButton
        function PMShutdownButtonPushed(app, event)
            app.nnp.enterWaiting();
            % Add confirmation dialog here
            resp = uiconfirm(app.UIFigure, 'Do you want to power down the Power Module? You will need to use control tower to restart!', "Shutdown PM", 'Options', ["Yes", "No"], "DefaultOption", 2);
            if isequal(resp, 'Yes')
                response=app.nnp.nmt(7, '9E'); %enable scripts
                if response==hex2dec('9E')
                    dmessage='PM Shutdown';
                    tbc=[1,1,1];
                else
                    dmessage=app.nnp.lastError;
                    tbc=[1,1,.7];
                end
            else
                dmessage='Cancelled';
                tbc=[1,1,1];
            end
            app.MessagesTextArea.Value=dmessage;
            app.MessagesTextArea.BackgroundColor=tbc;
        end

        % Button pushed function: StatusButton
        function StatusButtonPushed(app, event)
            app.getNNP_Status();
            
        end

        % Value changed function: PMVNETEditField
        function PMVNETEditFieldValueChanged(app, event)
            value = app.PMVNETEditField.Value;
            if value > 9.5 || value < 4.6
                msgbox('PM Network Voltage must be between 4.6 and 9.5 V')
            else
            end
          
        end

        % Button pushed function: DisableScriptsButton
        function DisableScriptsButtonPushed(app, event)
             app.nnp.enterWaiting();
            response=app.nnp.nmt(7, '8F'); %disable scripts
            app.ResponseMessage(response==hex2dec('8F'),'Scripts Disabled');
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            vnet=app.PMVNETEditField.Value;
            if vnet > 9.5 || vnet < 4.6
                app.PMVNETEditField.BackgroundColor = [0.99,0.67,0.49];
                pause(2);
                app.PMVNETEditField.BackgroundColor = [1 1 1];
            else
                resp=app.nnp.setVNET(vnet);
                if resp == 1
                    app.MessagesTextArea.Value='Successfully changed Network Voltage';
                    app.PMVNETEditField.BackgroundColor = [0 1 0]; %value flashes green
                    pause(2);
                    app.PMVNETEditField.BackgroundColor = [1 1 1];
                    %show V Net in Status Box
                    vnet = double(app.nnp.read(7, '3010', 0, 'uint8'))/10; %VNET
                    if ~isempty(vnet)
                        app.VnetEditField.Value = vnet;
                    else
                         vnet = [];
                         app.MessagesTextArea.Value='Error changing Network Voltage';
                    end
                    
                end
                
            end

            
        end

        % Button pushed function: ScanforNodesButton
        function ScanforNodesButtonPushed(app, event)
            app.ListBox.Items = {'-'};
            % app.enableNodeTabSelectionsAll('off');
            if app.AllNodesButton.Value == 1 
                nodes = 1:127;
                h = uiprogressdlg(app.UIFigure, 'Title', 'Scanning for Nodes...', 'Cancelable','on');
            else 
                nodes = sscanf( app.NodeScanListTextArea.Value{1}, '%u')';
                h = [];
                if isempty(nodes)
                    msgbox('No nodes listed') 
                    app.enableAll('on');
                    return
                elseif any(nodes>127) || any(nodes<1) 
                    msgbox('Nodes must be between 1 and 127') 
                    app.enableAll('on');
                    return
                end
            end
            
            cnt = 2;
            
            for node = nodes
                if ~isempty(h) 
                    if ~isvalid(h)  %if waitbar closed assume user wants to cancel
                        app.ScanforNodesButton.Text = 'Scan';
                        app.enableAll('on');
                        return;
                        %h = waitbar(node/nodes(end), 'Scanning for Nodes...'); if waitbar was closed, reopen it
                    end
                    h.Value = (node/nodes(end));
                    h.Message = sprintf("Node: %d", node);
                    if(h.CancelRequested)
                        delete(h);
                    end
                    drawnow
                    % waitbar(node/nodes(end), h)
                end
                app.ScanforNodesButton.Text ='Scanning...';
                
                [resp, err] = app.nnp.transmit(7, uint8([0, 0, node, 1, 0]), 0, hex2dec('3c'));
                if err == 0 && length(resp) == 9 && resp(1) == 8 

                    nodeStr = sprintf('Node %u', resp(8));

                    snStr = sprintf('SN %u', (double(resp(4))+double(resp(5))*256));
                    
                    if resp(3)<2 %Bootloader Mode
                        switch resp(7) 
                            case 3,    typeStr = 'Test Device';
                            case 4,    typeStr = 'PulseGenerator (PG4)';
                            case 5,    typeStr = 'Biopotential   (BP2)';
                            otherwise, typeStr = 'Unknown Module Type!';
                        end
                        revStr = sprintf('BootRev %u.%u', resp([2 6])); 
                        if resp(3)==1
                            selStr = ' - SELECTED'; 
                        else
                            selStr = [];
                        end
                    elseif resp(3)==2 %App Mode
                         switch resp(2) 
                            case 4,    typeStr = 'PulseGenerator (PG4)';
                            case 5,    typeStr = 'Biopotential   (BP2)';
                            otherwise, typeStr = 'Unknown Module Type!';
                         end
                         revStr = sprintf('AppRev %u', double(resp(6))+double(resp(7))*256) ;
                         selStr = ' - RUNNING APP';
                    else
                        typeStr = [];
                        revStr = [];
                        selStr = '- INVALID';
                        %invalid Selection Mode
                    end
                        
                    if resp(9)>1
                        extrasStr = ['(+' num2str(resp(9)-1) , 'other nodes)'];
                    else
                        extrasStr = [];
                    end
                    if resp(8) ~= node
                        extrasStr = [extrasStr, '(expected Node' num2str(resp(8)), ')'];
                    end
                    
                    app.ListBox.Items{cnt} = [nodeStr, ' - ', snStr, ' - ', typeStr, ' - ' revStr, selStr, extrasStr];
                    cnt=cnt+1;
                elseif err~=1
                    if app.AllNodesButton.Value ~= 1
                        app.ListBox.Items{cnt} = sprintf('...may have missed node %u ', node);
                    else
                        app.ListBox.Items{cnt} = sprintf('Node %u - External Error', node);
                    end
                    cnt=cnt+1;
                elseif app.AllNodesButton.Value ~= 1
                    app.ListBox.Items{cnt} = sprintf('Node %u - Not on Network', node);
                    cnt = cnt+1;
                end  
            end
            if ~isempty(h)
                close(h)
            end
            app.ScanforNodesButton.Text = 'Scan';
            app.enableAll('on');
        end

        % Value changed function: SelectNodeButton
        function SelectNodeButtonValueChanged(app, event)
            tryselect = app.SelectNodeButton.Value;
            node = app.NodeEditField.Value;
            
            if tryselect==1
                sn = [0 0];
                app.SelectNodeButton.Text = 'Selecting...';              
            else
                sn = uint8([rem(app.SerialEditField.Value, 256), floor(app.SerialEditField.Value/256)]); %LB, HB
                app.SelectNodeButton.Text = 'Deselecting...';
            end
            
            for i=1:2
                %RM_ESTABLISH_COM
                [resp, err] = app.nnp.transmit(7, uint8([sn, node, 1, 0]), 0, hex2dec('3c'));
                if err
                    if err> 1
                        app.handleErrors(err, 'Select Node');
                    else
                       msgbox('Node may not be on the network. Run a scan to see available nodes.', 'Select Node', 'help') 
                    end
                    if tryselect == 1
                        app.DeselectNode();
                    else
                        app.SelectNode();
                    end
                    return
                end
                
                if length(resp) == 9
                    if resp(1) ~= 8
                        msgbox('Invalid bootloader response length byte', 'RM Bootloader Connect', 'error' );
                        if tryselect == 1
                            app.DeselectNode();
                        else
                            app.SelectNode();
                        end
                        return
                    end
                    
                    if resp(8) ~= node
                         msgbox('Node responding did not match requested node', 'RM Bootloader Connect', 'error' )
                        if tryselect == 1
                            app.DeselectNode();
                        else
                            app.SelectNode();
                        end
                        return
                    end
                    
                    if resp(9) > 1
                        msgbox([num2str(resp(9)) ' nodes matched the requested node.  Connecting to first responder'], 'RM Bootloader Connect' )
                    end
                    
                    selectionstate = resp(3);
                    if tryselect==0
                        if selectionstate ~= 0 
                            msgbox('Node not deselected', 'RM Bootloader Connect', 'error' )
                            app.SelectNode();
                            return
                        elseif  selectionstate == 2
                            msgbox('Node is running Application and is not selected', 'RM Bootloader Connect') 
                        end
                        app.DeselectNode();
                        break; %exit for loop
                    else
                        if selectionstate == 1 && i==1 
                            msgbox('Node is already selected', 'RM Bootloader Connect' )
                        elseif selectionstate == 0 && i==2
                            msgbox('Node not selected', 'RM Bootloader Connect', 'error' )
                            app.DeselectNode();
                            return
                        elseif selectionstate == 2 
                            msgbox('Node is running Application.  Power cycle network to enter bootloader mode', 'RM Bootloader Connect', 'error' )
                            app.DeselectNode();
                            return
                        end
                        app.SerialEditField.Value = double(resp(4))+double(resp(5))*256;
                        switch resp(7)
                            case 4,    typeStr = 'PulseGenerator (PG4)';
                            case 5,    typeStr = 'Biopotential   (BP2)';
                            otherwise, typeStr = 'Unknown Module Type!';
                        end
                        app.ModuleTypeEditField.Value = typeStr;
                        app.BootRevEditField.Value = num2str(resp(6));
                        sn = resp(4:5);
                        app.SelectNode();
                    end
                elseif length(resp) == 8 && resp(6) <= 56
                    msgbox('Requested node is running old bootloader.  Not yet supported via this app.  Use Control Editor', 'RM Bootloader Connect', 'error' );    
%                     app.DeselectNode();
%                     return

                    selectionstate = resp(3);
                    if tryselect==0
                        if selectionstate ~= 0 
                            msgbox('Node not deselected', 'RM Bootloader Connect', 'error' )
                            app.SelectNode();
                            return
                        elseif  selectionstate == 2
                            msgbox('Node is running Application and is not selected', 'RM Bootloader Connect') 
                        end
                        app.DeselectNode();
                        break; %exit for loop
                    else
                        if selectionstate == 1 && i==1 
                            msgbox('Node is already selected', 'RM Bootloader Connect' )
                        elseif selectionstate == 0 && i==2
                            msgbox('Node not selected', 'RM Bootloader Connect', 'error' )
                            app.DeselectNode();
                            return
                        elseif selectionstate == 2 
                            msgbox('Node is running Application.  Power cycle network to enter bootloader mode', 'RM Bootloader Connect', 'error' )
                            app.DeselectNode();
                            return
                        end
                        app.SerialEditField.Value = double(resp(4))+double(resp(5))*256;
                        switch resp(7)
                            case 4,    typeStr = 'PulseGenerator (PG4)';
                            case 5,    typeStr = 'Biopotential   (BP2)';
                            otherwise, typeStr = 'Unknown Module Type!';
                        end
                        app.ModuleTypeEditField.Value = typeStr;
                        app.BootRevEditField.Value = num2str(resp(6));
                        sn = resp(4:5);
                        app.SelectNode();
                    end
                else
                    msgbox('Invalid bootloader response length', 'RM Bootloader Connect', 'error' )
                    %app.SelectNodeButton.Text = errstr;
                    if trySelect == 1
                        app.DeselectNode()
                    else
                        app.SelectNode();
                    end
                    return
                end
            end
            app.enableAll('on');
        end

        % Button pushed function: ChangeNodeButton
        function ChangeNodeButtonPushed(app, event)
            resp = inputdlg(['Current Node # for selected node is ', num2str(app.NodeEditField.Value), '. New Node #:'], 'Change Node',1);
            try newNode = str2double(resp{1});
                if newNode <1 || newNode >127 
                    msgbox('Node must be between 1 and 127','ChangeNode', 'error')
                    return;
                end
                 [resp, err] = app.nnp.transmit(7, uint8([0, 0, newNode, 1, 7]), 0, hex2dec('3c'));
                 if err > 0 || length(resp) ~= 2 || ~all(resp==[1 newNode])
                     if err > 0 
                        app.handleErrors(err, 'Change Node Failed');
                     else
                          msgbox('Node was not changed for unknown reason','ChangeNode', 'error')
                     end
                     return;
                 else
                    app.NodeEditField.Value = newNode; 
                 end
            catch
                msgbox('Node number not valid','ChangeNode', 'error')
                return;
            end
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            str = app.ListBox.Value;
            result = sscanf(str, 'Node %u - SN %u');
            
            if length(result)==2
                app.NodeEditField.Value = result(1);
                app.SerialEditField.Value = result(2);
            else
                app.NodeEditField.Value = 0;
                app.SerialEditField.Value = 0;
            end
        end

        % Button pushed function: NetworkBOOTLOADERButton
        function NetworkBOOTLOADERButtonPushed(app, event)
             if app.nnp.enterWaiting()
                if app.nnp.networkOnBootloader()
                    dmessage = 'PM Network ON - Remote Modules in Bootloader Mode';
                    tbc=[1,1,1];
                    app.NetworkLamp.Color=[.7,.7,.7];
                else
                    dmessage=app.nnp.lastError;
                    tbc=[1,1,.7];
                    app.NetworkLamp.Color=[.7,.7,.7];
                end
            else
                dmessage=app.nnp.lastError;
                tbc=[1,1,.7];
            end
            app.MessagesTextArea.Value=dmessage;
            app.MessagesTextArea.BackgroundColor=tbc;

            
        end

        % Button pushed function: NetworkOFFButton_2
        function NetworkOFFButton_2Pushed(app, event)
            if app.nnp.enterWaiting()
                if app.nnp.networkOff()
                    dmessage = 'Network Off';
                     tbc=[1,1,1];
                     app.NetworkLamp.Color='red';
                     app.DeselectNode();
                else
                    dmessage=app.nnp.lastError;
                    tbc=[1,1,.7];
                    app.NetworkLamp.Color=[.7,.7,.7];
                end
            else
                dmessage=app.nnp.lastError;
                    tbc=[1,1,.7];
            end
            app.MessagesTextArea.Value=dmessage;
            app.MessagesTextArea.BackgroundColor=tbc;
        end

        % Button pushed function: ZeroPWButton
        function ZeroPWButtonPushed(app, event)
            app.PWEditField.Value=0;
             %resets PW to zero
            app.setStim();
        end

        % Value changed function: PAEditField
        function PAEditFieldValueChanged(app, event)
            app.setStim();
            
        end

        % Button pushed function: PAUpButton
        function PAUpButtonPushed(app, event)
            pa = app.PAEditField.Value;
            inc = app.PWincEditField_2.Value;
            pa = pa + inc;
            if pa > 200
                pa = 200;
            end
            app.PAEditField.Value = pa;
            app.PAEditFieldValueChanged([]);
        end

        % Button pushed function: PADnButton
        function PADnButtonPushed(app, event)
            pa = app.PAEditField.Value;
            inc = app.PWincEditField_2.Value;
            pa = pa - inc;
            if pa < 0
                pa = 0;
            end
            app.PAEditField.Value = pa;
            app.PAEditFieldValueChanged([]);
        end

        % Button pushed function: StimOnButton
        function StimOnButtonPushed(app, event)
            figure(app.UIFigure);
            if app.stimOn
                StimOffButtonPushed(app, []);
                app.ZeroPWButtonPushed();
                return;
            end

            app.enableNodeTabSelectionsAll('off');

            retry = 0;
            nodeErr=-1;
            while(nodeErr)             
                error = app.setStim();
                if error
                    nodeErr = app.NodeEditField_2.Value;
                else
                    nodeErr = 0;
                end
                retry = retry+1;
                if retry == 3
                    [modes, ~, ~, net] = app.nnp.getStatus();
                    if net==0
                        resp = questdlg('Network not On.  Turn on network?', 'Profiler');
                        if isequal(resp, 'Yes')
                            if ~app.nnp.networkOn()
                                msgbox('Could not turn on Network', 'Profiler', 'error');
                            end
                        end
                    elseif net==1
                        if nodeErr>0
                            if ~isequal(modes(nodeErr), 'Waiting')
                                resp = questdlg(['Node(s) ' num2str(nodeErr') ' is not online and in waiting mode.  Cycle Network?'], 'Profiler');
                                if isequal(resp, 'Yes')
                                    if ~app.nnp.networkOff()
                                        msgbox('Could not turn off Network', 'Profiler', 'error');
                                    end
                                    if ~app.nnp.networkOn()
                                        msgbox('Could not turn on Network', 'Profiler', 'error');
                                    end
                                end
                            else
                                msgbox(['Node(s) ' num2str(nodeErr') ' is online and in waiting mode, but could not be set'], 'Profiler', 'error');
                            end
                        end
                    else
                         msgbox('Network status could not be determined', 'Profiler', 'error');
                    end
                    return;
                end
            end
            if app.nnp.enterTestStim
                app.stimOn = true;
                app.PWEditField.FontColor = [0 0 0];
                app.StimOnButton.BackgroundColor = [0 1.0 0];
                app.StimOnButton.FontColor = [0 0 0];

                app.NextNodeButton.Enable = 'off';
                app.PrevNodeButton.Enable = 'off';
                app.NextChButton.Enable = 'off';
                app.PrevChButton.Enable = 'off';
            else
                app.TopMessage('Error Stimulating');
            end
        end

        % Button pushed function: StimOffButton
        function StimOffButtonPushed(app, event)
             figure(app.UIFigure);
             app.enableNodeTabSelectionsAll('on');
            if app.nnp.enterWaiting
                app.stimOn = false;
                app.PWEditField.FontColor = [0.8 0.8 0.8];
                app.UIFigure.Color = [0.94,0.94,0.94];
                
                app.StimOnButton.BackgroundColor = [0.94,0.94,0.94];
                app.StimOnButton.FontColor = [0.7 0.7 0.7];

                app.NextNodeButton.Enable = 'on';
                app.PrevNodeButton.Enable = 'on';
                app.NextChButton.Enable = 'on';
                app.PrevChButton.Enable = 'on';
            else
                app.TopMessage('Error Turning OFF Stim');
            end 
        end

        % Button pushed function: PWUpButton
        function PWUpButtonPushed(app, event)
            figure(app.UIFigure);
            inc = app.PWincEditField.Value;
            value = app.PWEditField.Value + inc;
            if value > 255
                value = 255;
            end
            if value == 255
                uialert(app.UIFigure, 'Pulse Width is maxed out', 'Maxed out');
            end
            app.PWEditField.Value = value;
            
            %apply Stim
            app.setStim();
        end

        % Button pushed function: PWDnButton
        function PWDnButtonPushed(app, event)
            figure(app.UIFigure);
            inc = app.PWincEditField.Value;
            value = app.PWEditField.Value - inc;
            if value < 0
                value = 0;
            end
            app.PWEditField.Value = value;
            
            %apply Stim
            app.setStim();
        end

        % Button pushed function: NextNodeButton
        function NextNodeButtonPushed(app, event)
            app.ZeroPWButtonPushed();
            node = app.NodeEditField_2.Value;
            node = node + 1;
            if node > 6
                node = 6;
                msgbox('max node to stim is node 6')
            end
            app.NodeEditField_2.Value = node;
    
        end

        % Button pushed function: PrevNodeButton
        function PrevNodeButtonPushed(app, event)
            app.ZeroPWButtonPushed();
            node = app.NodeEditField_2.Value;
            node = node - 1;
            if node < 1
                node = 1;
            end
            app.NodeEditField_2.Value = node;
        end

        % Button pushed function: NextChButton
        function NextChButtonPushed(app, event)
            app.ZeroPWButtonPushed();
            chan = app.ChanEditField.Value;
            chan = chan + 1;
            if chan > 4
                chan = 4;
            end
            app.ChanEditField.Value = chan;
        end

        % Button pushed function: PrevChButton
        function PrevChButtonPushed(app, event)
            app.ZeroPWButtonPushed();
            chan = app.ChanEditField.Value;
            chan = chan - 1;
            if chan < 1
                chan = 1;
            end
            app.ChanEditField.Value = chan;
        end

        % Value changed function: NetworkScannerButton
        function NetworkScannerButtonValueChanged(app, event)
            netscannerg(app.nnp,app.UIFigure)
        end

        % Value changed function: ODAccessButton
        function ODAccessButtonValueChanged(app, event)
            odtest(app.nnp,app.UIFigure);
        end

        % Button pushed function: ConfigAPButton_5
        function ConfigAPButton_5Pushed(app, event)
            app.nnp.enterWaiting();
            response=app.nnp.nmt(7, '8F'); %disable scripts
            app.ResponseMessage(response==hex2dec('8F'),'Scripts Disabled');
        end

        % Button pushed function: ConfigAPButton_4
        function ConfigAPButton_4Pushed(app, event)
            app.nnp.enterWaiting();            
            response=app.nnp.nmt(7, '8E'); %enable scripts
            app.ResponseMessage(response==hex2dec('8E'),'Scripts Enabled');
        end

        % Button down function: StimulationTab
        function StimulationTabButtonDown(app, event)
            if app.NNPAccessPointButton.Value
            app.enableStimTabSelectionsAll('on'); %enables all Stimulation Features
            % app.enableNodeTabSelectionsAll('off'); %disables Node Selection panel
            else
            app.enableStimTabSelectionsAll('off');
            app.enableNodeTabSelectionsAll('off');
            end
        end

        % Button down function: NodeNumberTab
        function NodeNumberTabButtonDown(app, event)
            if app.NNPAccessPointButton.Value
            app.enableStimTabSelectionsAll('off'); %disables all Stimulation Features
            % app.enableNodeTabSelectionsAll('on'); %enables Node Selection panel
            else
            app.enableStimTabSelectionsAll('off');
            app.enableNodeTabSelectionsAll('off');
            end
        end

        % Value changed function: ChanEditField
        function ChanEditFieldValueChanged(app, event)
            value = app.ChanEditField.Value;
            
            %reset Pulse Width
            app.PWEditField.Value = 0;
        end

        % Value changed function: NodeEditField_2
        function NodeEditField_2ValueChanged(app, event)
            value = app.NodeEditField_2.Value;
            
             %reset Pulse Width
            app.PWEditField.Value = 0;
        end

        % Button pushed function: StartupScriptButton
        function StartupScriptButtonPushed(app, event)
            response=app.nnp.nmt(7, '8E'); %enable scripts
            if response==hex2dec('8E')
               app.TopMessage='Scripts Enabled';
                response=app.nnp.nmt(7,'B3',7); % Run startup script
                if response==hex2dec('B3')
                    app.TopMessage='Scripts enabled and Startup Ran';
                else
                   app.TopMessage='Scripts Enabled, but encountered error ';
                end
            else
            end
        end

        % Button pushed function: PMBootloaderButton
        function PMBootloaderButtonPushed(app, event)
            pmbootloader(app.nnp);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NNPSurgeryToolLabel = uilabel(app.UIFigure);
            app.NNPSurgeryToolLabel.Text = 'NNP Surgery Tool';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NodeNumberTab = uitab(app.TabGroup);
            app.NodeNumberTab.Tooltip = {'Press to gather data to scan nodes'};
            app.NodeNumberTab.Title = 'Node Number';
            app.NodeNumberTab.ButtonDownFcn = createCallbackFcn(app, @NodeNumberTabButtonDown, true);
            app.NodeNumberTab.Tag = '*To test the Network connection btwn all implanted Modules and assign node #s to each module';
            app.NodeConfigurePanel = uipanel(app.NodeNumberTab);
            app.NodeConfigurePanel.Title = 'Node Configure';
            app.ScanforNodesButton = uibutton(app.NodeConfigurePanel, 'push');
            app.ScanforNodesButton.ButtonPushedFcn = createCallbackFcn(app, @ScanforNodesButtonPushed, true);
            app.ScanforNodesButton.Enable = 'off';
            app.ScanforNodesButton.Text = 'Scan for Nodes';
            app.ListBox = uilistbox(app.NodeConfigurePanel);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.ScanModeButtonGroup = uibuttongroup(app.NodeConfigurePanel);
            app.ScanModeButtonGroup.Enable = 'off';
            app.ScanModeButtonGroup.Title = 'Scan Mode';
            app.AllNodesButton = uiradiobutton(app.ScanModeButtonGroup);
            app.AllNodesButton.Enable = 'off';
            app.AllNodesButton.Text = 'All Nodes';
            app.ListedNodesButton = uiradiobutton(app.ScanModeButtonGroup);
            app.ListedNodesButton.Enable = 'off';
            app.ListedNodesButton.Text = 'Listed Nodes';
            app.ListedNodesButton.Value = true;
            app.PowerModuleSettingsPanel = uipanel(app.NodeConfigurePanel);
            app.PowerModuleSettingsPanel.Enable = 'off';
            app.PowerModuleSettingsPanel.Title = 'Power Module Settings';
            app.SerialEditField_2Label = uilabel(app.PowerModuleSettingsPanel);
            app.SerialEditField_2Label.Text = 'Serial #';
            app.PMPCBSerialEditField = uieditfield(app.PowerModuleSettingsPanel, 'numeric');
            app.PMPCBSerialEditField.Editable = 'off';
            app.PMPCBSerialEditField.Enable = 'off';
            app.PMPCBSerialEditField.Value = Inf;
            app.AppRevEditFieldLabel = uilabel(app.PowerModuleSettingsPanel);
            app.AppRevEditFieldLabel.Text = 'App Rev';
            app.PMAppRevisionEditField = uieditfield(app.PowerModuleSettingsPanel, 'numeric');
            app.PMAppRevisionEditField.Editable = 'off';
            app.PMAppRevisionEditField.Enable = 'off';
            app.PMAppRevisionEditField.Value = Inf;
            app.NetworkBOOTLOADERButton = uibutton(app.PowerModuleSettingsPanel, 'push');
            app.NetworkBOOTLOADERButton.ButtonPushedFcn = createCallbackFcn(app, @NetworkBOOTLOADERButtonPushed, true);
            app.NetworkBOOTLOADERButton.Enable = 'off';
            app.NetworkBOOTLOADERButton.Tooltip = {'Turn network on for PM only, Remote Modules are put in Bootloader mode'};
            app.NetworkBOOTLOADERButton.Text = {'Network '; 'BOOTLOADER'};
            app.NetworkOFFButton_2 = uibutton(app.PowerModuleSettingsPanel, 'push');
            app.NetworkOFFButton_2.ButtonPushedFcn = createCallbackFcn(app, @NetworkOFFButton_2Pushed, true);
            app.NetworkOFFButton_2.Enable = 'off';
            app.NetworkOFFButton_2.Tooltip = {'Place apps in waiting mode and turn network off'};
            app.NetworkOFFButton_2.Text = {'Network '; 'OFF'};
            app.SelectNodeButton = uibutton(app.NodeConfigurePanel, 'state');
            app.SelectNodeButton.ValueChangedFcn = createCallbackFcn(app, @SelectNodeButtonValueChanged, true);
            app.SelectNodeButton.Enable = 'off';
            app.SelectNodeButton.Text = 'Select Node';
            app.ChangeNodeButton = uibutton(app.NodeConfigurePanel, 'push');
            app.ChangeNodeButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeNodeButtonPushed, true);
            app.ChangeNodeButton.Enable = 'off';
            app.ChangeNodeButton.Text = 'Change Node #';
            app.NodeEditFieldLabel = uilabel(app.NodeConfigurePanel);
            app.NodeEditFieldLabel.Enable = 'off';
            app.NodeEditFieldLabel.Text = 'Node #';
            app.NodeEditField = uieditfield(app.NodeConfigurePanel, 'numeric');
            app.NodeEditField.Limits = [0 127];
            app.NodeEditField.Editable = 'off';
            app.NodeEditField.Enable = 'off';
            app.NodeScanListTextAreaLabel = uilabel(app.NodeConfigurePanel);
            app.NodeScanListTextAreaLabel.Enable = 'off';
            app.NodeScanListTextAreaLabel.Text = 'Node Scan List';
            app.NodeScanListTextArea = uitextarea(app.NodeConfigurePanel);
            app.NodeScanListTextArea.Enable = 'off';
            app.NodeScanListTextArea.Value = {'1 2 3 4 6 9'};
            app.SerialEditFieldLabel = uilabel(app.NodeConfigurePanel);
            app.SerialEditFieldLabel.Enable = 'off';
            app.SerialEditFieldLabel.Text = 'Serial #';
            app.SerialEditField = uieditfield(app.NodeConfigurePanel, 'numeric');
            app.SerialEditField.Editable = 'off';
            app.SerialEditField.Enable = 'off';
            app.ModuleTypeEditFieldLabel = uilabel(app.NodeConfigurePanel);
            app.ModuleTypeEditFieldLabel.Enable = 'off';
            app.ModuleTypeEditFieldLabel.Text = 'Module Type';
            app.ModuleTypeEditField = uieditfield(app.NodeConfigurePanel, 'text');
            app.ModuleTypeEditField.Editable = 'off';
            app.ModuleTypeEditField.Enable = 'off';
            app.BootRevEditFieldLabel = uilabel(app.NodeConfigurePanel);
            app.BootRevEditFieldLabel.Enable = 'off';
            app.BootRevEditFieldLabel.Text = 'Boot Rev';
            app.BootRevEditField = uieditfield(app.NodeConfigurePanel, 'text');
            app.BootRevEditField.Editable = 'off';
            app.BootRevEditField.Enable = 'off';
            app.StimulationTab = uitab(app.TabGroup);
            app.StimulationTab.Title = 'Stimulation';
            app.StimulationTab.ButtonDownFcn = createCallbackFcn(app, @StimulationTabButtonDown, true);
            app.StimulatePanel = uipanel(app.StimulationTab);
            app.StimulatePanel.Title = 'Stimulate';
            app.PrevChButton = uibutton(app.StimulatePanel, 'push');
            app.PrevChButton.ButtonPushedFcn = createCallbackFcn(app, @PrevChButtonPushed, true);
            app.PrevChButton.Enable = 'off';
            app.PrevChButton.Text = '◄';
            app.NextChButton = uibutton(app.StimulatePanel, 'push');
            app.NextChButton.ButtonPushedFcn = createCallbackFcn(app, @NextChButtonPushed, true);
            app.NextChButton.Enable = 'off';
            app.NextChButton.Text = '►';
            app.PrevNodeButton = uibutton(app.StimulatePanel, 'push');
            app.PrevNodeButton.ButtonPushedFcn = createCallbackFcn(app, @PrevNodeButtonPushed, true);
            app.PrevNodeButton.Enable = 'off';
            app.PrevNodeButton.Text = '◄';
            app.NextNodeButton = uibutton(app.StimulatePanel, 'push');
            app.NextNodeButton.ButtonPushedFcn = createCallbackFcn(app, @NextNodeButtonPushed, true);
            app.NextNodeButton.Enable = 'off';
            app.NextNodeButton.Text = '►';
            app.StimOnButton = uibutton(app.StimulatePanel, 'push');
            app.StimOnButton.ButtonPushedFcn = createCallbackFcn(app, @StimOnButtonPushed, true);
            app.StimOnButton.Enable = 'off';
            app.StimOnButton.Text = 'Stim On';
            app.StimOffButton = uibutton(app.StimulatePanel, 'push');
            app.StimOffButton.ButtonPushedFcn = createCallbackFcn(app, @StimOffButtonPushed, true);
            app.StimOffButton.Enable = 'off';
            app.StimOffButton.Text = 'Stim Off';
            app.PAEditField = uieditfield(app.StimulatePanel, 'numeric');
            app.PAEditField.Limits = [0 20];
            app.PAEditField.ValueDisplayFormat = '%.1f';
            app.PAEditField.ValueChangedFcn = createCallbackFcn(app, @PAEditFieldValueChanged, true);
            app.PAEditField.Enable = 'off';
            app.PAEditField.Value = 10;
            app.PAUpButton = uibutton(app.StimulatePanel, 'push');
            app.PAUpButton.ButtonPushedFcn = createCallbackFcn(app, @PAUpButtonPushed, true);
            app.PAUpButton.Enable = 'off';
            app.PAUpButton.Text = '▲';
            app.PADnButton = uibutton(app.StimulatePanel, 'push');
            app.PADnButton.ButtonPushedFcn = createCallbackFcn(app, @PADnButtonPushed, true);
            app.PADnButton.Enable = 'off';
            app.PADnButton.Text = '▼';
            app.ZeroPWButton = uibutton(app.StimulatePanel, 'push');
            app.ZeroPWButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroPWButtonPushed, true);
            app.ZeroPWButton.Enable = 'off';
            app.ZeroPWButton.Text = {'Reset '; 'PW'};
            app.ChanEditFieldLabel = uilabel(app.StimulatePanel);
            app.ChanEditFieldLabel.Enable = 'off';
            app.ChanEditFieldLabel.Text = 'Chan';
            app.ChanEditField = uieditfield(app.StimulatePanel, 'numeric');
            app.ChanEditField.Limits = [1 4];
            app.ChanEditField.RoundFractionalValues = 'on';
            app.ChanEditField.ValueDisplayFormat = '%.0f';
            app.ChanEditField.ValueChangedFcn = createCallbackFcn(app, @ChanEditFieldValueChanged, true);
            app.ChanEditField.Enable = 'off';
            app.ChanEditField.Value = 1;
            app.NodeEditField_2Label = uilabel(app.StimulatePanel);
            app.NodeEditField_2Label.Enable = 'off';
            app.NodeEditField_2Label.Text = 'Node';
            app.NodeEditField_2 = uieditfield(app.StimulatePanel, 'numeric');
            app.NodeEditField_2.Limits = [1 127];
            app.NodeEditField_2.RoundFractionalValues = 'on';
            app.NodeEditField_2.ValueDisplayFormat = '%.0f';
            app.NodeEditField_2.ValueChangedFcn = createCallbackFcn(app, @NodeEditField_2ValueChanged, true);
            app.NodeEditField_2.Enable = 'off';
            app.NodeEditField_2.Value = 1;
            app.IncrementLabel = uilabel(app.StimulatePanel);
            app.IncrementLabel.Enable = 'off';
            app.IncrementLabel.Text = 'Increment';
            app.PWincEditField = uieditfield(app.StimulatePanel, 'numeric');
            app.PWincEditField.Limits = [0 20];
            app.PWincEditField.RoundFractionalValues = 'on';
            app.PWincEditField.ValueDisplayFormat = '%.0f';
            app.PWincEditField.Enable = 'off';
            app.PWincEditField.Value = 15;
            app.PWEditField = uieditfield(app.StimulatePanel, 'numeric');
            app.PWEditField.Limits = [0 255];
            app.PWEditField.RoundFractionalValues = 'on';
            app.PWEditField.ValueDisplayFormat = '%.0f';
            app.PWEditField.Enable = 'off';
            app.PWDnButton = uibutton(app.StimulatePanel, 'push');
            app.PWDnButton.ButtonPushedFcn = createCallbackFcn(app, @PWDnButtonPushed, true);
            app.PWDnButton.Enable = 'off';
            app.PWDnButton.Text = '◄';
            app.PWUpButton = uibutton(app.StimulatePanel, 'push');
            app.PWUpButton.ButtonPushedFcn = createCallbackFcn(app, @PWUpButtonPushed, true);
            app.PWUpButton.Enable = 'off';
            app.PWUpButton.Text = '►';
            app.PAmALabel = uilabel(app.StimulatePanel);
            app.PAmALabel.Enable = 'off';
            app.PAmALabel.Text = 'PA (mA)';
            app.PWuSLabel = uilabel(app.StimulatePanel);
            app.PWuSLabel.Enable = 'off';
            app.PWuSLabel.Text = 'PW (uS)';
            app.IncrementLabel_2 = uilabel(app.StimulatePanel);
            app.IncrementLabel_2.Text = 'Increment';
            app.PWincEditField_2 = uieditfield(app.StimulatePanel, 'numeric');
            app.PWincEditField_2.Limits = [0 20];
            app.PWincEditField_2.RoundFractionalValues = 'on';
            app.PWincEditField_2.ValueDisplayFormat = '%.0f';
            app.PWincEditField_2.Enable = 'off';
            app.PWincEditField_2.Value = 2;
            app.MiscTab = uitab(app.TabGroup);
            app.MiscTab.Title = 'Misc.';
            app.NetworkScannerButton = uibutton(app.MiscTab, 'state');
            app.NetworkScannerButton.ValueChangedFcn = createCallbackFcn(app, @NetworkScannerButtonValueChanged, true);
            app.NetworkScannerButton.Enable = 'off';
            app.NetworkScannerButton.Tooltip = {'Enable or disable access point'};
            app.NetworkScannerButton.Text = 'Network Scanner';
            app.ODAccessButton = uibutton(app.MiscTab, 'state');
            app.ODAccessButton.ValueChangedFcn = createCallbackFcn(app, @ODAccessButtonValueChanged, true);
            app.ODAccessButton.Enable = 'off';
            app.ODAccessButton.Tooltip = {'Enable or disable access point'};
            app.ODAccessButton.Text = {'Object Dictionary'; 'Access'};
            app.ScriptsPanel_2 = uipanel(app.MiscTab);
            app.ScriptsPanel_2.Title = 'Scripts';
            app.ConfigAPButton_4 = uibutton(app.ScriptsPanel_2, 'push');
            app.ConfigAPButton_4.ButtonPushedFcn = createCallbackFcn(app, @ConfigAPButton_4Pushed, true);
            app.ConfigAPButton_4.Enable = 'off';
            app.ConfigAPButton_4.Tooltip = {'Configure access point radio settings'};
            app.ConfigAPButton_4.Text = 'Enable';
            app.ConfigAPButton_5 = uibutton(app.ScriptsPanel_2, 'push');
            app.ConfigAPButton_5.ButtonPushedFcn = createCallbackFcn(app, @ConfigAPButton_5Pushed, true);
            app.ConfigAPButton_5.Enable = 'off';
            app.ConfigAPButton_5.Tooltip = {'Configure access point radio settings'};
            app.ConfigAPButton_5.Text = 'Disable';
            app.StartupScriptButton = uibutton(app.ScriptsPanel_2, 'push');
            app.StartupScriptButton.ButtonPushedFcn = createCallbackFcn(app, @StartupScriptButtonPushed, true);
            app.StartupScriptButton.Enable = 'off';
            app.StartupScriptButton.Tooltip = {'Run the startup script which is currently loaded in the power module'};
            app.StartupScriptButton.Text = {'Run Startup'; 'Script'};
            app.PMBootloaderButton = uibutton(app.MiscTab, 'push');
            app.PMBootloaderButton.ButtonPushedFcn = createCallbackFcn(app, @PMBootloaderButtonPushed, true);
            app.PMBootloaderButton.Enable = 'off';
            app.PMBootloaderButton.Text = 'PM Bootloader';
            app.MessagesTextAreaLabel = uilabel(app.UIFigure);
            app.MessagesTextAreaLabel.Text = 'Messages';
            app.MessagesTextArea = uitextarea(app.UIFigure);
            app.PMShutdownPanel = uipanel(app.UIFigure);
            app.PMShutdownPanel.Title = 'PM Shutdown';
            app.PMShutdownButton = uibutton(app.PMShutdownPanel, 'push');
            app.PMShutdownButton.ButtonPushedFcn = createCallbackFcn(app, @PMShutdownButtonPushed, true);
            app.PMShutdownButton.Enable = 'off';
            app.PMShutdownButton.Text = {'PM'; 'Shutdown'};
            app.AllowShutdownCheckBox = uicheckbox(app.PMShutdownPanel);
            app.AllowShutdownCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllowShutdownCheckBoxValueChanged, true);
            app.AllowShutdownCheckBox.Enable = 'off';
            app.AllowShutdownCheckBox.Tooltip = {'Check to enable system shutdown'};
            app.AllowShutdownCheckBox.Text = 'Allow Shutdown';
            app.StatusPanel = uipanel(app.UIFigure);
            app.StatusPanel.Title = 'Status';
            app.NetworkLampLabel = uilabel(app.StatusPanel);
            app.NetworkLampLabel.Enable = 'off';
            app.NetworkLampLabel.Text = 'Network';
            app.NetworkLamp = uilamp(app.StatusPanel);
            app.NetworkLamp.Enable = 'off';
            app.NetworkLamp.Tooltip = {'Network Status'; 'Green - ON'; 'Red - OFF'};
            app.RefreshButton = uibutton(app.StatusPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Enable = 'off';
            app.RefreshButton.Tooltip = {'Update the status bar'};
            app.RefreshButton.Text = 'Refresh';
            app.PMTempEditFieldLabel = uilabel(app.StatusPanel);
            app.PMTempEditFieldLabel.Enable = 'off';
            app.PMTempEditFieldLabel.Text = 'PM Temp';
            app.PMTempEditField = uieditfield(app.StatusPanel, 'numeric');
            app.PMTempEditField.ValueDisplayFormat = '%2.1f';
            app.PMTempEditField.Editable = 'off';
            app.PMTempEditField.Enable = 'off';
            app.PMTempEditField.Tooltip = {'Temperature of the Power Module in Celsius'};
            app.VbatteryEditFieldLabel = uilabel(app.StatusPanel);
            app.VbatteryEditFieldLabel.Enable = 'off';
            app.VbatteryEditFieldLabel.Text = 'Vbattery';
            app.VbatteryEditField = uieditfield(app.StatusPanel, 'numeric');
            app.VbatteryEditField.ValueDisplayFormat = '%1.1f';
            app.VbatteryEditField.Editable = 'off';
            app.VbatteryEditField.Enable = 'off';
            app.VbatteryEditField.Tooltip = {'Battery level in Volts. (lowest value out of the 3)'};
            app.VnetEditFieldLabel = uilabel(app.StatusPanel);
            app.VnetEditFieldLabel.Enable = 'off';
            app.VnetEditFieldLabel.Text = 'Vnet';
            app.VnetEditField = uieditfield(app.StatusPanel, 'numeric');
            app.VnetEditField.ValueDisplayFormat = '%1.1f';
            app.VnetEditField.Editable = 'off';
            app.VnetEditField.Enable = 'off';
            app.VnetEditField.Tooltip = {'Network Power in Volts'};
            app.RSSIEditFieldLabel = uilabel(app.StatusPanel);
            app.RSSIEditFieldLabel.Enable = 'off';
            app.RSSIEditFieldLabel.Text = 'RSSI';
            app.RSSIEditField = uieditfield(app.StatusPanel, 'numeric');
            app.RSSIEditField.ValueDisplayFormat = '%3.1f';
            app.RSSIEditField.Editable = 'off';
            app.RSSIEditField.Enable = 'off';
            app.RSSIEditField.Tooltip = {'Received Signal Strength Indication for last received radio message'};
            app.PowerNetworkPanel = uipanel(app.UIFigure);
            app.PowerNetworkPanel.Title = 'Power Network';
            app.NetworkOFFButton_3 = uibutton(app.PowerNetworkPanel, 'push');
            app.NetworkOFFButton_3.ButtonPushedFcn = createCallbackFcn(app, @NetworkOFFButton_3Pushed, true);
            app.NetworkOFFButton_3.Enable = 'off';
            app.NetworkOFFButton_3.Tooltip = {'Place apps in waiting mode and turn network off'};
            app.NetworkOFFButton_3.Text = {'Network '; 'OFF'};
            app.NetworkONButton = uibutton(app.PowerNetworkPanel, 'push');
            app.NetworkONButton.ButtonPushedFcn = createCallbackFcn(app, @NetworkONButtonPushed, true);
            app.NetworkONButton.Enable = 'off';
            app.NetworkONButton.Tooltip = {'Turn network on and set system in app mode'};
            app.NetworkONButton.Text = {'Network '; 'ON'};
            app.VnetUpdatePanel = uipanel(app.PowerNetworkPanel);
            app.VnetUpdatePanel.Title = 'Vnet Update';
            app.UpdateButton = uibutton(app.VnetUpdatePanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Enable = 'off';
            app.UpdateButton.Tooltip = {'Button to change network voltage'};
            app.UpdateButton.Text = 'Update';
            app.PMVNETEditField = uieditfield(app.VnetUpdatePanel, 'numeric');
            app.PMVNETEditField.ValueChangedFcn = createCallbackFcn(app, @PMVNETEditFieldValueChanged, true);
            app.PMVNETEditField.Enable = 'off';
            app.PMVNETEditField.Value = 8.5;
            app.AccessPointPanel = uipanel(app.UIFigure);
            app.AccessPointPanel.Title = 'Access Point';
            app.NNPAccessPointButton = uibutton(app.AccessPointPanel, 'state');
            app.NNPAccessPointButton.ValueChangedFcn = createCallbackFcn(app, @NNPAccessPointButtonValueChanged, true);
            app.NNPAccessPointButton.Tooltip = {'Connection to talk with implanted Power Module'};
            app.NNPAccessPointButton.Text = 'NNP Access Point Open';
            app.RadioButton = uibutton(app.AccessPointPanel, 'push');
            app.RadioButton.ButtonPushedFcn = createCallbackFcn(app, @RadioButtonPushed, true);
            app.RadioButton.Enable = 'off';
            app.RadioButton.Tooltip = {'Configure access point radio settings'};
            app.RadioButton.Text = 'Radio';
            app.StatusButton = uibutton(app.AccessPointPanel, 'push');
            app.StatusButton.ButtonPushedFcn = createCallbackFcn(app, @StatusButtonPushed, true);
            app.StatusButton.Enable = 'off';
            app.StatusButton.Tooltip = {'Gives state of PM and the connected modules'};
            app.StatusButton.Text = 'Status';
            app.ClearMessageButton = uibutton(app.UIFigure, 'push');
            app.ClearMessageButton.ButtonPushedFcn = createCallbackFcn(app, @ClearMessageButtonPushed, true);
            app.ClearMessageButton.Tooltip = {'Clear messages'};
            app.ClearMessageButton.Text = 'clear';
            app.ScriptsPanel = uipanel(app.UIFigure);
            app.ScriptsPanel.Enable = 'off';
            app.ScriptsPanel.Title = 'Scripts';
            app.DisableScriptsButton = uibutton(app.ScriptsPanel, 'push');
            app.DisableScriptsButton.ButtonPushedFcn = createCallbackFcn(app, @DisableScriptsButtonPushed, true);
            app.DisableScriptsButton.Enable = 'off';
            app.DisableScriptsButton.Tooltip = {'Disable all running scripts'};
            app.DisableScriptsButton.Text = 'Disable';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nnpsurgerysuite

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end