classdef nnputil < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NNPUtilitiesUIFigure  matlab.ui.Figure
        ODtestButton          matlab.ui.control.Button
        NNPAccessPointButton  matlab.ui.control.StateButton
        ConfigAPButton        matlab.ui.control.Button
        BurnInButton          matlab.ui.control.Button
        BPTestButton          matlab.ui.control.Button
        PGTestButton          matlab.ui.control.Button
        PMTestButton          matlab.ui.control.Button
        RMBootloaderButton    matlab.ui.control.Button
        PMBootloaderButton    matlab.ui.control.Button
    end


    properties (Access = public)
        nnp = []; % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.NNPAccessPointButton.Value = 1;
            app.NNPAccessPointButtonValueChanged();
        end

        % Button pushed function: RMBootloaderButton
        function RMBootloaderButtonPushed(app, event)
            rmbootloader(app.nnp);
        end

        % Button pushed function: ConfigAPButton
        function ConfigAPButtonPushed(app, event)
            radioconfig(app.nnp);
        end

        % Button pushed function: PGTestButton
        function PGTestButtonPushed(app, event)
            pgtest(app.nnp);
        end

        % Close request function: NNPUtilitiesUIFigure
        function NNPUtilitiesUIFigureCloseRequest(app, event)
            if ~isempty(app.nnp)
                delete(app.nnp)
            end
            delete(app)
        end

        % Button pushed function: BurnInButton
        function BurnInButtonPushed(app, event)
            burnintest(app.nnp);
        end

        % Button pushed function: BPTestButton
        function BPTestButtonPushed(app, event)
            bptest(app.nnp);
        end

        % Button pushed function: PMTestButton
        function PMTestButtonPushed(app, event)
            pmtest(app.nnp);
        end

        % Button pushed function: PMBootloaderButton
        function PMBootloaderButtonPushed(app, event)
            pmbootloader(app.nnp);
        end

        % Value changed function: NNPAccessPointButton
        function NNPAccessPointButtonValueChanged(app, event)
            if ~isempty(app.nnp) 
                 delete(app.nnp)
            end
            
            if app.NNPAccessPointButton.Value 
                app.NNPAccessPointButton.Text = 'NNP Access Point-Select Port';
                 while true
                    app.nnp = NNPAPI();
                    if isempty(app.nnp.port)
                        resp = questdlg('No port chosen for NNP Access Point.  Plug in AccessPoint and try again.  If you do not want to try again, press No and the application will close');
                        if isequal(resp, 'No')
                            close(app.NNPUtilitiesUIFigure);
                            return;
                        end
                    else
                        break;
                    end
                 end
                 app.NNPAccessPointButton.Text = 'NNP Access Point Open';
            else
                app.NNPAccessPointButton.Text = 'NNP Access Point Closed';
             end
            
                        
        end

        % Button pushed function: ODtestButton
        function ODtestButtonPushed(app, event)
            odtest(app.nnp);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NNPUtilitiesUIFigure = uifigure('Visible', 'off');
            app.NNPUtilitiesUIFigure.Name = 'NNP Utilities';
            app.NNPUtilitiesUIFigure.CloseRequestFcn = createCallbackFcn(app, @NNPUtilitiesUIFigureCloseRequest, true);
            app.PMBootloaderButton = uibutton(app.NNPUtilitiesUIFigure, 'push');
            app.PMBootloaderButton.ButtonPushedFcn = createCallbackFcn(app, @PMBootloaderButtonPushed, true);
            app.PMBootloaderButton.Text = {'Power Module'; 'Bootloader'};
            app.RMBootloaderButton = uibutton(app.NNPUtilitiesUIFigure, 'push');
            app.RMBootloaderButton.ButtonPushedFcn = createCallbackFcn(app, @RMBootloaderButtonPushed, true);
            app.RMBootloaderButton.Text = {'Remote Module'; 'Bootloader'};
            app.PMTestButton = uibutton(app.NNPUtilitiesUIFigure, 'push');
            app.PMTestButton.ButtonPushedFcn = createCallbackFcn(app, @PMTestButtonPushed, true);
            app.PMTestButton.Text = {'PM '; 'Functional Test'};
            app.PGTestButton = uibutton(app.NNPUtilitiesUIFigure, 'push');
            app.PGTestButton.ButtonPushedFcn = createCallbackFcn(app, @PGTestButtonPushed, true);
            app.PGTestButton.Text = {'PG '; 'Functional Test'};
            app.BPTestButton = uibutton(app.NNPUtilitiesUIFigure, 'push');
            app.BPTestButton.ButtonPushedFcn = createCallbackFcn(app, @BPTestButtonPushed, true);
            app.BPTestButton.Text = {'BP '; 'Functional Test'};
            app.BurnInButton = uibutton(app.NNPUtilitiesUIFigure, 'push');
            app.BurnInButton.ButtonPushedFcn = createCallbackFcn(app, @BurnInButtonPushed, true);
            app.BurnInButton.Text = 'Burn-In';
            app.ConfigAPButton = uibutton(app.NNPUtilitiesUIFigure, 'push');
            app.ConfigAPButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigAPButtonPushed, true);
            app.ConfigAPButton.Text = 'Radio Configuration';
            app.NNPAccessPointButton = uibutton(app.NNPUtilitiesUIFigure, 'state');
            app.NNPAccessPointButton.ValueChangedFcn = createCallbackFcn(app, @NNPAccessPointButtonValueChanged, true);
            app.NNPAccessPointButton.Text = 'NNP Access Point';
            app.ODtestButton = uibutton(app.NNPUtilitiesUIFigure, 'push');
            app.ODtestButton.ButtonPushedFcn = createCallbackFcn(app, @ODtestButtonPushed, true);
            app.ODtestButton.Text = 'OD test';
            % Show the figure after all components are created
            app.NNPUtilitiesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nnputil

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NNPUtilitiesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NNPUtilitiesUIFigure)
        end
    end
end