classdef patternDisplay < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DisplayPatternGroupUIFigure  matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        OpenMenu                     matlab.ui.container.Menu
        PNGMenu                      matlab.ui.container.Menu
        Menu                         matlab.ui.container.Menu
        PlotsPanel                   matlab.ui.container.Panel
        AmpUITable                   matlab.ui.control.Table
        PWUITable                    matlab.ui.control.Table
        PWAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        newPatterns=[]; 
        templateStructure=[]; %fields: 'templateSize','templateCommandArray','templateNames','templateMuscleTypes','templateMatrix'
        profileTable=[];
        colNum=struct('LastChecked', 1, 'Node', 2, 'Ch', 3, 'Muscle', 4,...
                      'Amp', 5, 'ThPW', 6, 'MaxPW', 7, 'Type', 8, 'Flag', 9, 'Notes', 10);
        ActivePatterns = {};   
        currentGroup=1;
        nnps =[]; %NNP settings file for user (group, channel names)
        saveFile=[];
        Plots=[];
        pwLegend=[];
        maxType=13;
        template=[]; % we could just use the template structure, but I chose such long names, this was easier...
        templateName=[];
        commandArray;
        commandLevels;
        createdPatterns=[];
        usedProfRows=[];
        parentApp = [];
        callingFigure=[];
        keepOrCancel=false;
        pwTable=[];
        ampTable=[];
        fGroup=[];
        autoPrint=false;
        pathDir=[];
    end
    
    methods (Access = public)


        function plotPatterns(app)
        fgRows=size(app.ActivePatterns,1);   
        for patRow=1:fgRows
        % Plot on the Graph
                     if patRow == 1
                        hold(app.PWAxes, 'off');
                        % use sprintf to get better formatting of title 
                        % set these when loading
%                         G=app.GroupEditField.Value;
%                         C=app.cmdIDEditField.Value;
%                         N=app.NameEditField.Value;
%                         T=app.templateName;
%                         plotTitle=sprintf('FG %d, Cmd %d,  T-%s,  %s',G, C, T, N);
%                         app.PWAxes.Title.String=plotTitle;
                    else
                        hold(app.PWAxes, 'on');
                     end
                     % Note, if I wanted to be able to highlight the plots
                    pw=app.ActivePatterns{patRow}.pw;
                    cmd=app.ActivePatterns{patRow}.cmd;
                    app.Plots{patRow}=plot(app.PWAxes,cmd,pw,'LineStyle','-','Marker','.','LineWidth',1,'MarkerSize',10);
% need list of muscle names based on channels used in pattern
                    node=app.ActivePatterns{patRow}.node;
                    ch=app.ActivePatterns{patRow}.ch;
                    name=app.nnps.channels{node,ch}.name;
                    legendList{patRow}=char(name); % add type here
                    
%                     end
%                 end
        end
        if size(app.ActivePatterns,1)>0
            app.pwLegend=legend(app.PWAxes,legendList,'Location','eastoutside');
        end
        end
        
        function fillTables(app)
            % Determine how wide the table needs to be, based upon the command
            nRow=size(app.ActivePatterns,1);
            cmd=app.ActivePatterns{1}.cmd;
            breakPoints=size(cmd,2);
            nCol=breakPoints+3;
            vTypes=["double","double","cellstr","double","double","double","double","double","double","double","double","double","double","double","double","double","double","double","double","double","double"];
            app.pwTable=table('Size',[nRow,nCol],'VariableTypes',vTypes(1:nCol));
            for i=1:breakPoints
                cmdNames{i}=num2str(cmd(i));
            end
            % update plot display
            xTicks=cmd;
            app.PWAxes.XTick = xTicks;
            app.PWAxes.XTickLabel = cmdNames;
            app.PWAxes.XMinorTick = 'off';
            app.PWAxes.YMinorTick = 'off';
            app.PWAxes.XGrid = 'on';
            app.PWAxes.YGrid = 'on';
            app.PWAxes.XMinorGrid = 'off';
            app.PWAxes.YMinorGrid = 'off';
            vNames=[{'Node','Chan','Muscle'},cmdNames];
            %vNames={'Node','Chan','Muscle','0','5','55','105','155','205','255'};
            app.pwTable.Properties.VariableNames=vNames;
            %PWTable.Properties.VariableTypes=vTypes;
            app.ampTable=app.pwTable;

            for i=1:nRow
                % get Node
                node=app.ActivePatterns{i}.node;
                chan=app.ActivePatterns{i}.ch;
                pw=app.ActivePatterns{i}.pw;
                amp=app.ActivePatterns{i}.amp;
                muscle=cellstr(app.nnps.channels{node,chan}.name);
                app.pwTable{i,1}=node;
                app.pwTable{i,2}=chan;
                app.pwTable{i,3}=muscle;
                app.ampTable{i,1}=node;
                app.ampTable{i,2}=chan;
                app.ampTable{i,3}=muscle;
                for j=1:breakPoints
                    app.pwTable{i,j+3}=pw(j);
                    app.ampTable{i,j+3}=amp(j);
                end
            end
            % update ui table
            app.PWUITable.Data=app.pwTable;
            app.PWUITable.ColumnName=vNames;
            app.AmpUITable.Data=app.ampTable;
            app.AmpUITable.ColumnName=vNames;
            G=app.currentGroup;
            C=app.nnps.groups{app.currentGroup}.cmdID;
            N=app.nnps.groups{app.currentGroup}.name;
       
            plotTitle=sprintf('FG %d, Cmd %d, %s',G, C, N);
            app.PWAxes.Title.String=plotTitle;
        end
    end

    methods (Access = private)
        
        
        function loadfGroup(app,ld)
            %ld=fGroup structure, same as file structure
            app.nnps.channels = ld.channels;
            app.nnps.nodes = ld.nodes;
            app.nnps.groups = ld.groups;
            app.ActivePatterns=ld.ActivePatterns;
            app.currentGroup=ld.currentGroup;

            app.plotPatterns();
            app.fillTables();
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
 %      callingFigure, fGroup
 na=nargin;   
 a1=varargin;
 if nargin>1
     app.callingFigure=varargin{1};
     if nargin>2
         fGroup=varargin{2};
         app.loadfGroup(fGroup);
     end
     if nargin>3 % 1 means autoPrint, close figure and return to sender
         if varargin{3}>0
             app.autoPrint=true;
         end
         % For now going to close figure afterward, but I could change the
         % third argument to be 1 autoPrint and 2 autoPrint+autoClose
     end
      if nargin>4
        app.pathDir=varargin{4}; % add path
      end
 end
 % This needs a signficant amount of work yet to make it useful.
% I need to replace the profile table with one that displays the following
% for each row in the active pattern:
% Node, Channel, Name, PW1, PW2, ... PW9

% above that I need a table that displays CMD1, CMD2, ... CMD9

% below I need the same thing but with Amp
% Node, Channel, Name, Amp1, Amp2, ... Amp9

% Then I need to be able to export the data into an Excel spreadsheet.

            %      PARENTAPP, templateStructure, profileTable, group, cmdID

%             app.parentApp = varargin{1};
%             app.templateStructure=varargin{2};
%             app.profileTable=varargin{3};
%             app.NameEditField.Value=varargin{6};
%             app.TemplateDropDown.Items=app.templateStructure.templateNames;            
%             app.TemplateDropDown.ItemsData=[1:app.templateStructure.templateSize];
%             app.templateName=app.TemplateDropDown.Items{1}; %set as first by default          
%             app.maxType=size(app.templateStructure.templateMuscleTypes.MuscleType,1);
%             app.GroupEditField.Value=varargin{4};
%             app.cmdIDEditField.Value=varargin{5};
%             app.commandArray=app.templateStructure.templateCommandArray;
%             app.commandLevels=size(app.commandArray,2);
%             app.template(:,:)=app.templateStructure.templateMatrix(1,:,:); % default is first in dropdown
%             app.PWUITable.Data = app.profileTable;
app.DisplayPatternGroupUIFigure.Position=[100,50,850,950]; %start shifted down so I can scale to fit 
% and still be able to resize and scroll
if app.autoPrint
    app.DisplayPatternGroupUIFigure.Visible='off';
    app.PNGMenuSelected(); % automatically export and print
%     pause(1); % not sure if I need a pause
    app.DisplayPatternGroupUIFigureCloseRequest(); % close afterward
end
        end

        % Close request function: DisplayPatternGroupUIFigure
        function DisplayPatternGroupUIFigureCloseRequest(app, event)
%             if ~app.keepOrCancel
%                 %if not selected, then treat like cancel
%                 app.parentApp.createdPatterns=[];
%                 app.parentApp.addCreatedPatterns=false;
%             end
            if ishandle(app.callingFigure) % check that is is valid
                figure(app.callingFigure); % bring to front
            end
            delete(app)
            
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            % read in Active Patterns and nnps information
        [filename, filepath] = uigetfile('.mat', 'Select NNP System File');
        if filename
            ld = load([filepath filename]);
%             TempFile = [filepath filename];  % save filename as temporary until the file is checked

        else
            return;
        end
        fields = {'ActivePatterns','channels', 'nodes', 'groups','currentGroup'};
        iMissing = find(~isfield(ld, fields));

        str = [];
        for i=1:length(iMissing)
            str = [str ', ' fields{iMissing(i)}];
        end
        if ~isempty(str)
            warndlg(['Invalid NNP System File.  Does not contain variable(s): ' str '.'], 'Open File')
            return;
        end
        %               If the file is invalid, we don't want to update anything, including the file name
        %               app.saveFile = TempFile;
        app.loadfGroup(ld);
%         app.nnps.channels = ld.channels;
%         app.nnps.nodes = ld.nodes;
%         app.nnps.groups = ld.groups;
%         app.ActivePatterns=ld.ActivePatterns;
%         app.currentGroup=ld.currentGroup;
% 
%         app.plotPatterns();
%         app.fillTables();
        figure(app.DisplayPatternGroupUIFigure); %return focus after nnp opened if needed  
        end

        % Menu selected function: PNGMenu
        function PNGMenuSelected(app, event)
             %% save high resolution image of plots panel
            % second time through could use clf to clear figure 
            % I could step through all the pattern files and save them in
            % one Excel file, each with a separate sheet name

            if exist('fCap','var') && ishandle(fCap)
                clf(fCap)
            else
                fCap=figure;
            end
            if app.autoPrint
                fCap.Visible='off';  % hide display to print faster
            end 
            fCap.Position=[1000 55 700 940];
            group=app.currentGroup;
%             savenamepng=['FG',num2str(group),'.png'];
            printnamepng=['FGp',num2str(group),'.png'];

%             savenameemf=['FG',num2str(group),'.emf'];
%             savenamejpg=['FG',num2str(group),'.jpg'];
%             savenamesvg=['FG',num2str(group),'.svg'];
            sheetName=['FG',num2str(group)];
            savenameXL=['FG',num2str(group),'.xlsx'];
            if ~isempty(app.pathDir)
                printnamepng=fullfile(app.pathDir,printnamepng);
                 savenameXL=fullfile(app.pathDir,savenameXL);
            end
%             newH=copyobj(app.PWAxes,fCap);
%copyobj([handles.main_plot legend(handles.main_plot],f_tmp);
            newH=copyobj([app.PWAxes legend(app.PWAxes)],fCap);
            newH(1).Position=[25 530 530 390];
            newH(2).Location='eastoutside';
            td=app.PWUITable.Data;
            uit=uitable(fCap,'Data',table2cell(td));
            CN=td.Properties.VariableNames;
            uit.ColumnName={CN{:}};
            uit.RowName=[];
            uit.ColumnWidth={50,50,100,40,40,40,40,40,40,40,40,40};
            uit.Position=[50 270 600 250];
            tdRows=size(td,1);
            startRowAmp=['A',num2str(tdRows+5)];
            td2=app.AmpUITable.Data;
            uit2=uitable(fCap,'Data',table2cell(td2));
            uit2.Position=[50 10 600 250];
            uit2.ColumnName={CN{:}};
            uit2.RowName=[];
            uit2.ColumnWidth={50,50,100,40,40,40,40,40,40,40,40,40};
%             saveas(fCap,savenamepng);
%             saveas(fCap,savenameemf,'meta');
%             saveas(fCap,savenamejpg,'jpeg');
%             saveas(fCap,savenamesvg,'svg');
            print(fCap,printnamepng,'-dpng','-r300')
            pwTitle={'PW'};
             writecell(pwTitle,savenameXL,'Sheet',sheetName,'Range','A1');
            data1=[CN;table2cell(td)];
            % How to add titles?  Could create cell array pwTitle={'PW'};
%             writecell(data1,savenameXL,'Sheet','PW');
            writecell(data1,savenameXL,'Sheet',sheetName,'Range','A2');

            startRowAmp=['A',num2str(tdRows+4)];
            ampTitle={'Amp'};
            writecell(ampTitle,savenameXL,'Sheet',sheetName,'Range',startRowAmp);
            data2=[CN;table2cell(td2)];
            startRowAmp=['A',num2str(tdRows+5)];
            writecell(data2,savenameXL,'Sheet',sheetName,'Range',startRowAmp);          
            close(fCap);
            clear('fCap');
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DisplayPatternGroupUIFigure = uifigure('Visible', 'off');
            app.DisplayPatternGroupUIFigure.Name = 'Display Pattern Group';
            app.DisplayPatternGroupUIFigure.CloseRequestFcn = createCallbackFcn(app, @DisplayPatternGroupUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.DisplayPatternGroupUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.PNGMenu = uimenu(app.DisplayPatternGroupUIFigure);
            app.PNGMenu.MenuSelectedFcn = createCallbackFcn(app, @PNGMenuSelected, true);
            app.PNGMenu.Text = 'PNG';
            app.Menu = uimenu(app.PNGMenu);
            app.Menu.Text = 'Menu';
            app.PlotsPanel = uipanel(app.DisplayPatternGroupUIFigure);
            app.PlotsPanel.Title = 'Plots';
            app.PWAxes = uiaxes(app.PlotsPanel);
            title(app.PWAxes, 'Title')
            xlabel(app.PWAxes, 'Command')
            ylabel(app.PWAxes, 'PW')
            zlabel(app.PWAxes, 'Z')
            app.PWAxes.XLim = [0 255];
            app.PWAxes.YLim = [0 255];
            app.PWAxes.XTick = [0 32 64 96 128 160 192 224 255];
            app.PWAxes.XTickLabel = {'0'; '32'; '64'; '96'; '128'; '160'; '192'; '224'; '255'};
            app.PWAxes.YTick = [0 50 100 150 200 255];
            app.PWAxes.YTickLabel = {'0'; '50'; '100'; '150'; '200'; '255'};
            app.PWUITable = uitable(app.PlotsPanel);
            app.PWUITable.ColumnName = {'Node'; 'Chan'; 'Muscle'; '0'; '5'; '55'; '105'; '155'; '205'; '255'; '-'; '-'};
            app.PWUITable.RowName = {};
            app.PWUITable.ColumnSortable = [true true true false false false false true false false false false];
            app.PWUITable.SelectionType = 'row';
            app.PWUITable.Multiselect = 'off';
            app.AmpUITable = uitable(app.PlotsPanel);
            app.AmpUITable.ColumnName = {'Node'; 'Chan'; 'Muscle'; '0'; '5'; '55'; '105'; '155'; '205'; '255'; '-'; '-'};
            app.AmpUITable.RowName = {};
            app.AmpUITable.ColumnSortable = [true true true false false false false true false false false false];
            app.AmpUITable.SelectionType = 'row';
            app.AmpUITable.Multiselect = 'off';
            % Show the figure after all components are created
            app.DisplayPatternGroupUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = patternDisplay(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DisplayPatternGroupUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DisplayPatternGroupUIFigure)
        end
    end
end