classdef pdoConfig < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        OpenMenu                       matlab.ui.container.Menu
        SaveMenu                       matlab.ui.container.Menu
        SaveAsMenu                     matlab.ui.container.Menu
        Label_3                        matlab.ui.control.Label
        UserIDEditField                matlab.ui.control.EditField
        WriteBPTempAccTPDOsButton      matlab.ui.control.Button
        Messages                       matlab.ui.control.TextArea
        MessagesTextAreaLabel          matlab.ui.control.Label
        ResetTPDOTabletoDefaultButton  matlab.ui.control.Button
        PMversionPanel                 matlab.ui.container.Panel
        PMverEditField                 matlab.ui.control.NumericEditField
        PMverEditFieldLabel            matlab.ui.control.Label
        PMVersionButton                matlab.ui.control.Button
        TpdosEditField                 matlab.ui.control.NumericEditField
        TpdosEditFieldLabel            matlab.ui.control.Label
        PGTestYPDOsButton              matlab.ui.control.Button
        WritePGTempAccTPDOsButton      matlab.ui.control.Button
        PMODSaveButton                 matlab.ui.control.Button
        WritePMTPDOsButton             matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        RPDOPMTab                      matlab.ui.container.Tab
        IndexSubindexTotalBits422byteinHexLabel  matlab.ui.control.Label
        PhysicalEMGConnectionsSwitch   matlab.ui.control.Switch
        PhysicalEMGConnectionsSwitchLabel  matlab.ui.control.Label
        NotethatPDO1isdatareturnedfromtheBPmoduleLabel  matlab.ui.control.Label
        PMRPDOLabel                    matlab.ui.control.Label
        x1400SettingsLabel             matlab.ui.control.Label
        RPDOsettingsTable              matlab.ui.control.Table
        x1600MappingLabel              matlab.ui.control.Label
        RPDOmapTable                   matlab.ui.control.Table
        TPDOPMTab                      matlab.ui.container.Tab
        IndexSubindexTotalBits422byteinHexLabel_2  matlab.ui.control.Label
        PMTPDOLabel                    matlab.ui.control.Label
        x1800SettingsLabel             matlab.ui.control.Label
        TPDOsettingsTable              matlab.ui.control.Table
        x1A00MappingLabel              matlab.ui.control.Label
        TPDOmapTable                   matlab.ui.control.Table
        StimTab                        matlab.ui.container.Tab
        Label_2                        matlab.ui.control.Label
        MappingFormatIndexSubindexTotalBits422byteinHexLabel  matlab.ui.control.Label
        ColEditField                   matlab.ui.control.NumericEditField
        ColEditFieldLabel              matlab.ui.control.Label
        WriteSwitch                    matlab.ui.control.Switch
        WriteSwitchLabel               matlab.ui.control.Label
        AccSetupButton                 matlab.ui.control.Button
        NOTEThissetsallofthePGnodestousethesameaccvalueLabel  matlab.ui.control.Label
        FilteredAccelerometersSwitch   matlab.ui.control.Switch
        FilteredAccelerometersLabel    matlab.ui.control.Label
        RpdoTestYPGPanel               matlab.ui.container.Panel
        PGRpdoID                       matlab.ui.control.Table
        PGRpdomapping                  matlab.ui.control.Table
        TpdoTempAccPanel               matlab.ui.container.Panel
        PGTpdomapping                  matlab.ui.control.Table
        StimNodesLabel                 matlab.ui.control.Label
        NodeListStim                   matlab.ui.control.TextArea
        BPTab                          matlab.ui.container.Tab
        Nnode180015Label               matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        MappingFormatIndexSubindexTotalBits422byteinHexLabel_2  matlab.ui.control.Label
        BPNodesLabel                   matlab.ui.control.Label
        NodeListBP                     matlab.ui.control.TextArea
        TpdoTempAccPanelBP             matlab.ui.container.Panel
        BPTpdomapping                  matlab.ui.control.Table
        WritePMRPDOButton              matlab.ui.control.Button
        ResetRPDOTabletoDefaultButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        nnp = [];
        RPDO = []; %create RPDO structure
        TPDO = []; %create TPDO structure
        myData=[]; %structure to read in from mat file
        PDOmap1600x8=[];  % separate variable if I don't need the whole structure but want to use the data later
        PGnodelist=[];
        BPnodelist=[];
        saveFile=[];
        callingFigure=[]; %handle to calling figure
        % Code for auto file open
         userFolders=struct;  % userfiles, code, user, config, data, log
         defaultSuffix='_PDO_Config.mat';
         defaultName='P00_PDO_Config.mat';
    end
    
    properties (Access = private)

    end
    
    methods (Access = private)
        
        function defaultRPDOpmSettings(app)
            
            app.RPDO.id = uint32(hex2dec({'00000689','00000189','00000181','00000182','00000183','00000184','00000185','00000186'})');
            app.RPDO.type = uint8(255); %process immediately when data received
            % RPDO.type = uint8(0); %process on next SYNC
            app.RPDO.inhibit = uint16(0); %not relevant for RPDO
            app.RPDO.compat = uint8(0); %script pointer for script that runs when PDO is received
            app.RPDO.timer = uint16(0); %not relevant for RPDO
            app.RPDO.settings=cell(5,8);
            for i=1:8
                app.RPDO.settings(1,i)=cellstr(dec2hex(app.RPDO.id(i),8));
                app.RPDO.settings(2,i)=cellstr(dec2hex(app.RPDO.type,2));
                app.RPDO.settings(3,i)=cellstr(dec2hex(app.RPDO.inhibit,4));
                app.RPDO.settings(4,i)=cellstr(dec2hex(app.RPDO.compat,2));
                app.RPDO.settings(5,i)=cellstr(dec2hex(app.RPDO.timer,4));
            end
            app.RPDOsettingsTable.Data=app.RPDO.settings;
        end
        
        function defaultRPDOpmMapping(app)
                   
           app.RPDOmapTable.Data=...
               {'1F53A208','1F53C008','1F53A808','1F53AC08','1F53B008','1F53B408','1F53B808','1F53BC08';...
                '1F532E08','1F53C108','1F53A908','1F53AD08','1F53B108','1F53B508','1F53B908','1F53BD08';...
                '1F53A408','1F53C208','1F53AA08','1F53AE08','1F53B208','1F53B608','1F53BA08','1F53BE08';...
                '1F53A508','1F53C308','1F53AB08','1F53AF08','1F53B308','1F53B708','1F53BB08','1F53BF08';...
                '1F53A308','1F53E208','1F53D608','1F53D808','1F53DA08','1F53DC08','1F53DE08','1F53E008';...
                '1F532F08','1F53E308','1F53D708','1F53D908','1F53DB08','1F53DD08','1F53DF08','1F53E108';...
                '1F53A608','1F53CA08','1F53C408','1F53C508','1F53C608','1F53C708','1F53C808','1F53C908';...
                '1F53A708','1F53D108','1F53CB08','1F53CC08','1F53CD08','1F53CE08','1F53CF08','1F53D008'};
            app.RPDO.mapping = cell(8,1);
            for i=1:8
                app.RPDO.mapping{i}=uint32(hex2dec(app.RPDOmapTable.Data(:,i)))'; %Convert one column at a time
            end    
        end
        
    
        function defaultTPDOpmSettings(app)
            app.TPDO.id = uint32(hex2dec({'00000187','00000287','00000387','00000487','000001C7','000002C7','80000000','80000000'})');
            %     app.TPDO.type = uint8(1); %sent with every sync, regardless if data has changed
            % app.TPDO.type = uint8(0);  %sent with every sync, only if data has changed
            app.TPDO.type = uint8(253); %disabled
            app.TPDO.inhibit = uint16(0);  %not relevant for synchronous
            app.TPDO.compat = uint8(0);  %not relevant for app.TPDO
            app.TPDO.timer = uint16(0); %not relevant for synchronous    
            for i=1:8
                app.TPDO.settings(1,i)=cellstr(dec2hex(app.TPDO.id(i),8));
                app.TPDO.settings(2,i)=cellstr(dec2hex(app.TPDO.type,2));
                app.TPDO.settings(3,i)=cellstr(dec2hex(app.TPDO.inhibit,4));
                app.TPDO.settings(4,i)=cellstr(dec2hex(app.TPDO.compat,2));
                app.TPDO.settings(5,i)=cellstr(dec2hex(app.TPDO.timer,4));
            end  
             app.TPDOsettingsTable.Data=app.TPDO.settings;
            
        end
        
        function defaultTPDOpmMapping(app)
            app.TPDOmapTable.Data=...
                {'30210108','30210908','30211108','30211908','30212108','30212908','00000000','00000000';...
                '30210208','30210A08','30211208','30211A08','30212208','30212A08','00000000','00000000';...
                '30210308','30210B08','30211308','30211B08','30212308','30212B08','00000000','00000000';...
                '30210408','30210C08','30211408','30211C08','30212408','30212C08','00000000','00000000';...
                '30210508','30210D08','30211508','30211D08','30212508','30212D08','00000000','00000000';...
                '30210608','30210E08','30211608','30211E08','30212608','30212E08','00000000','00000000';...
                '30210708','30210F08','30211708','30211F08','30212708','30212F08','00000000','00000000';...
                '30210808','30211008','30211808','30212008','30212808','30213008','00000000','00000000'};
            
            app.TPDO.mapping = cell(8,1);
            for i=1:8
                app.TPDO.mapping{i}=uint32(hex2dec(app.TPDOmapTable.Data(:,i)))'; %Convert one column at a time
            end
            %             app.TPDO.mapping{1} = uint32(hex2dec({'30210108','30210208','30210308','30210408','30210508','30210608','30210708','30210808'})');
            %             app.TPDO.mapping{2} = uint32(hex2dec({'30210908','30210a08','30210b08','30210c08','30210d08','30210e08','30210f08','30211008'})');
            %             app.TPDO.mapping{3} = uint32(hex2dec({'30211108','30211208','30211308','30211408','30211508','30211608','30211708','30211808'})');
            %             app.TPDO.mapping{4} = uint32(hex2dec({'30211908','30211a08','30211b08','30211c08','30211d08','30211e08','30211f08','30212008'})');
            %             app.TPDO.mapping{5} = uint32(hex2dec({'30212108','30212208','30212308','30212408','30212508','30212608','30212708','30212808'})');
            %             app.TPDO.mapping{6} = uint32(hex2dec({'30212908','30212a08','30212b08','30212c08','30212d08','30212e08','30212f08','30213008'})');
            %             app.TPDO.mapping{7} = uint32(hex2dec({'00000000','00000000','00000000','00000000','00000000','00000000','00000000','00000000'})');
            %             app.TPDO.mapping{8} = uint32(hex2dec({'00000000','00000000','00000000','00000000','00000000','00000000','00000000','00000000'})');
            %
        end
        
        function defaultTPDOpgmapping(app)
            if app.FilteredAccelerometersSwitch.Value == "On"
                app.PGTpdomapping.Data={'20110220','20030110','30000208','30000308','00000000','00000000','00000000','00000000'}';
                % need to add filter depth settings here.
                % need to read current settings and save settings with save
                % Maybe best to 

            else
                app.PGTpdomapping.Data={'20110120','20030110','30000208','30000308','00000000','00000000','00000000','00000000'}';
            end
        end
        
        function defaultRPDOpg(app)
            app.PGRpdomapping.Data={'32120110','32120210','32120310','32120410','00000000','00000000','00000000','00000000'}';
            app.PGRpdoID.Data={'00000187','00000287','00000387','00000487','000001C7','000002C7','00000407','00000507'}';
        end
        
        function PowerCycleNetwork(app)
             %power cycle the network to activate the RM CAN filters 
                bothSuccess=true;
                app.TopMessage('pause before power cycle');
                pause(1.5);
                app.nnp.enterWaiting();   % waiting mode
                success=app.nnp.networkOff();  % network off
                if success %true if successful
                    dmessage='Network Off';
                else
                    dmessage='Network Off failed';
                    bothSuccess=false;
                end
                app.TopMessage(dmessage);

                pause(.5);
                app.nnp.enterWaiting();   % waiting mode
                success=app.nnp.networkOn();  % network on
                if success %true if successful
                    dmessage='Network On';
                else
                    dmessage='Network On failed';
                    bothSuccess=false;
                end
                app.TopMessage(dmessage);
                if bothSuccess %true if successful
                    dmessage='Power Cycle succeed';
                else
                    dmessage='Power Cycle failed';
                end
                app.TopMessage(dmessage);
        end
    end
    
    methods (Access = public)
        
        function ResponseMessage(app,good,message)
            if good
                dmessage=message;
                tbc=[1,1,1];
            else
                dmessage=app.nnp.lastError;
                tbc=[1,1,.7];
            end
            app.TopMessage(dmessage);
            app.Messages.BackgroundColor=tbc;
        
        end
        
        function getPMversion(app)
            resp=double(app.nnp.read(7,'1018',3,'uint32')); % read revision number to test communications
            if ~isempty(resp)
               app.PMverEditField.Value=resp; 
               if resp>=405
                   app.TpdosEditField.Value=6;
               else
                   app.TpdosEditField.Value=8;
               end
            end
            app.ResponseMessage(~isempty(resp),sprintf('PM version %d',resp));
        end
        
        function defaultTPDObpmapping(app)
                app.BPTpdomapping.Data={'20110220','20030110','30000208','30000308','00000000','00000000','00000000','00000000'}';
        end
        
        function TopMessage(app,message)
            app.Messages.Value=vertcat(message, string(app.Messages.Value)); % put new message on top  
        end
        
        function ClearMessages(app)
               app.Messages.Value=''; % clear messages         
        end
        
        function OpenFileByName(app,filename,filepath)
            
            if filename
                ld = load(fullfile(filepath, filename));
            else
                return;
            end
            fields = {'TPDOsettingsTableData', 'PGRpdoIDData', 'PGRpdomappingData', 'PGTpdomappingData', ...
                'RPDO', 'RPDOmapTableData', 'RPDOsettingsTableData', 'TPDO', 'TPDOmapTableData', 'NodeListStimValue'};
            iMissing = find(~isfield(ld, fields));
            
            str = [];
            for i=1:length(iMissing)
                str = [str ', ' fields{iMissing(i)}];
            end
            if ~isempty(str)
                warndlg(['Invalid NNP System File.  Does not contain variable(s): ' str '.'], 'Open File')
                return;
            end
            %               If the file is invalid, we don't want to update anything, including the file name
            app.RPDO=ld.RPDO;
            app.RPDOsettingsTable.Data=ld.RPDOsettingsTableData;
            app.RPDOmapTable.Data=ld.RPDOmapTableData;
            app.TPDO=ld.TPDO;
            app.TPDOsettingsTable.Data=ld.TPDOsettingsTableData;
            app.TPDOmapTable.Data=ld.TPDOmapTableData;
            app.NodeListStim.Value=ld.NodeListStimValue;
            app.PGRpdoID.Data=ld.PGRpdoIDData;
            app.PGRpdomapping.Data=ld.PGRpdomappingData;
            app.PGTpdomapping.Data=ld.PGTpdomappingData;
            wflLogical=app.RPDOmapTable.Data{2,1}; % normal = '1F532E08' reversed = '1F532F08'
            if strcmpi(wflLogical,'1F532E08')
                % make switch normal
                app.PhysicalEMGConnectionsSwitch.Value = 'Normal';
            else
                %make switch reversed
                app.PhysicalEMGConnectionsSwitch.Value = 'Reversed';
            end
            figure(app.UIFigure); %return focus
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin<2
                NNP=[];
            else
                NNP=varargin{1};
            end
            useNNP=true;
            if isempty(NNP)  || ~isvalid(NNP) %if being called after nnp cancelled from another app
                app.nnp = NNPAPI();
                if isempty(app.nnp.port)
    %                 msgbox('No port chosen for NNP Access Point')
                     uialert(app.UIFigure,'No port chosen for NNP Access Point','No NNP AP')
                    delete(app.nnp)
                    figure(app.UIFigure); %return focus
                     useNNP=false;
    %                 return
                end
            else
                app.nnp = NNP;
            end

            if nargin>2
                app.callingFigure=varargin{2};                
            end
           if nargin>3  % third is userFolders
                app.userFolders=varargin{3};  % userfiles, code, user, config, data, log
                app.UserIDEditField.Value=app.userFolders.code;
           end
           if useNNP
            app.getPMversion();
           end
            app.RPDOsettingsTable.RowName={'ID','type','inhibit','compat','timer'};
            app.TPDOsettingsTable.RowName={'ID','type','inhibit','compat','timer'};
            app.defaultRPDOpmSettings();
            app.defaultRPDOpmMapping();
            app.defaultTPDOpmSettings();
            app.defaultTPDOpmMapping();
            app.defaultTPDOpgmapping();
            app.defaultTPDObpmapping();
            app.defaultRPDOpg();
            if isfield(app.userFolders,'config')&& ~isempty(app.userFolders.config)
               filename=[app.userFolders.code,app.defaultSuffix];
               app.defaultName=filename; % this is reduntant, could combine above
               filepath=app.userFolders.config;
               fullName=fullfile(filepath,filename);
               if exist(fullName,'file')==2  % make sure file exists before trying to open
                   app.OpenFileByName(filename,filepath);
               end
           end
        end

        % Button pushed function: ResetRPDOTabletoDefaultButton
        function ResetRPDOTabletoDefaultButtonPushed(app, event)
            app.defaultRPDOpmSettings();
            app.defaultRPDOpmMapping();
            
%             [fn, pn]=uigetfile('*.mat','Select PDO map file','PDOmap1600x8wfl.mat');
%             app.myData= matfile(fullfile(pn,fn)) ; %associates the matfile without loading it, a structure of pointers I think
%             app.PDOmap1600x8=app.myData.PDOmap1600x8; %reads the data from mat file into variable.  Use if I need variable elsewhere           
%             app.RPDOmapTable.Data=reshape(cellstr(dec2hex(app.PDOmap1600x8,8)),8,8)'; %display as hex, had to transpose due to cellstr function
%             app.RPDO.mapping = cell(8,1);
%             for i=1:8
%                 app.RPDO.mapping{i}=app.PDOmap1600x8(i,:); %located in PDOmap1600x8.mat
%             end
%             app.setPMRPDOsettings();
%             % The following may need to change if other PDOs added or MES has different node number
%             app.RPDO.id = uint32(hex2dec({'00000689','00000180','00000181','00000182','00000183','00000184','00000185','00000186'})');
%             app.RPDO.type = uint8(255); %process immediately when data received           
%             app.RPDO.inhibit = uint16(0); %not relevant for RPDO
%             app.RPDO.compat = uint8(0); %script pointer for script that runs when PDO is received
%             app.RPDO.timer = uint16(0); %not relevant for RPDO
%             app.RPDO.settings=cell(5,8);
%             for i=1:8
%                 app.RPDO.settings(1,i)=cellstr(dec2hex(app.RPDO.id(i),8));
%                 app.RPDO.settings(2,i)=cellstr(dec2hex(app.RPDO.type,8));
%                 app.RPDO.settings(3,i)=cellstr(dec2hex(app.RPDO.inhibit,8));
%                 app.RPDO.settings(4,i)=cellstr(dec2hex(app.RPDO.compat,8));
%                 app.RPDO.settings(5,i)=cellstr(dec2hex(app.RPDO.timer,8));
%             end
%             
%             app.RPDOsettingsTable.Data=app.RPDO.settings;
        end

        % Button pushed function: WritePMRPDOButton
        function WritePMRPDOButtonPushed(app, event)
            app.nnp.enterWaiting();  % need to make sure in waiting mode before save
            % % Write to OD 140i.n RPDO Settings
            % result shoud return 0 if successful.  Otherwise will return []
%             if ~isequal(result, 0)
%                 app.TopMessage('Error Writing ...');
%                 return;
%             else
%                 app.TopMessage('... to PM');
%             end 
            % Keep track of whether an error occured
            writeError=0; %start with no write errors
            app.TopMessage('Writing RPDOs...');
            for i=1:8
                %     for i=1:1
                app.RPDO.mapping{i}=uint32(hex2dec(app.RPDOmapTable.Data(:,i)))'; %Convert one column at a time

                result=app.nnp.write(7, ['160' num2str(i-1)], 1, app.RPDO.mapping{i}, 'uint32',8);
                if result~=0
                    writeError=writeError+1;
                end
               result=app.nnp.write(7, ['140' num2str(i-1)], 1, app.RPDO.id(i), 'uint32');
               if result~=0
                    writeError=writeError+1;
                end
                result=app.nnp.write(7, ['140' num2str(i-1)], 2, app.RPDO.type, 'uint8');
                if result~=0
                    writeError=writeError+1;
                end
                result=app.nnp.write(7, ['140' num2str(i-1)], 3, app.RPDO.inhibit, 'uint16');
                if result~=0
                    writeError=writeError+1;
                end
                result=app.nnp.write(7, ['140' num2str(i-1)], 4, app.RPDO.compat, 'uint8');
                if result~=0
                    writeError=writeError+1;
                end
                result=app.nnp.write(7, ['140' num2str(i-1)], 5, app.RPDO.timer, 'uint16');
                if result~=0
                    writeError=writeError+1;
                end
                pause(.25); % add this delay to allow serial port to catch up
            end
            dmessage=sprintf('%d errors writing RPOs to PM',writeError);
            app.TopMessage(dmessage);
        end

        % Button pushed function: WritePMTPDOsButton
        function WritePMTPDOsButtonPushed(app, event)
            
%             app.TPDO.mapping = cell(8,1);
%             app.TPDO.mapping{1} = uint32(hex2dec({'30210108','30210208','30210308','30210408','30210508','30210608','30210708','30210808'})');
%             app.TPDO.mapping{2} = uint32(hex2dec({'30210908','30210a08','30210b08','30210c08','30210d08','30210e08','30210f08','30211008'})');
%             app.TPDO.mapping{3} = uint32(hex2dec({'30211108','30211208','30211308','30211408','30211508','30211608','30211708','30211808'})');
%             app.TPDO.mapping{4} = uint32(hex2dec({'30211908','30211a08','30211b08','30211c08','30211d08','30211e08','30211f08','30212008'})');
%             app.TPDO.mapping{5} = uint32(hex2dec({'30212108','30212208','30212308','30212408','30212508','30212608','30212708','30212808'})');
%             app.TPDO.mapping{6} = uint32(hex2dec({'30212908','30212a08','30212b08','30212c08','30212d08','30212e08','30212f08','30213008'})');
%             app.TPDO.mapping{7} = uint32(hex2dec({'00000000','00000000','00000000','00000000','00000000','00000000','00000000','00000000'})');
%             app.TPDO.mapping{8} = uint32(hex2dec({'00000000','00000000','00000000','00000000','00000000','00000000','00000000','00000000'})');
%             
%             app.TPDO.id = uint32(hex2dec({'00000187','00000287','00000387','00000487','000001C7','000002C7','80000000','80000000'})');
%             %     app.TPDO.type = uint8(1); %sent with every sync, regardless if data has changed
%             % app.TPDO.type = uint8(0);  %sent with every sync, only if data has changed
%             app.TPDO.type = uint8(253); %disabled
%             app.TPDO.inhibit = uint16(0);  %not relevant for synchronous
%             app.TPDO.compat = uint8(0);  %not relevant for app.TPDO
%             app.TPDO.timer = uint16(0); %not relevant for synchronous
            
            %Write to OD 180i,m PM TPDOs
            writeError=0; %start with no write errors
            app.TopMessage('Writing TPDOs...');
            for i=1:app.TpdosEditField.Value % change to 6 starting with PM 405
                app.TPDO.mapping{i}=uint32(hex2dec(app.TPDOmapTable.Data(:,i)))'; %Convert one column at a time
                result=app.nnp.write(7, ['1A0' num2str(i-1)], 1, app.TPDO.mapping{i}, 'uint32',8);
                if result~=0
                    writeError=writeError+1;
                end
                result=app.nnp.write(7, ['180' num2str(i-1)], 1, app.TPDO.id(i), 'uint32');
                if result~=0
                    writeError=writeError+1;
                end
                result=app.nnp.write(7, ['180' num2str(i-1)], 2, app.TPDO.type, 'uint8');
                if result~=0
                    writeError=writeError+1;
                end
                result=app.nnp.write(7, ['180' num2str(i-1)], 3, app.TPDO.inhibit, 'uint16');
                if result~=0
                    writeError=writeError+1;
                end
                result=app.nnp.write(7, ['180' num2str(i-1)], 4, app.TPDO.compat, 'uint8');
                if result~=0
                    writeError=writeError+1;
                end
                result=app.nnp.write(7, ['180' num2str(i-1)], 5, app.TPDO.timer, 'uint16');
                if result~=0
                    writeError=writeError+1;
                end
             dmessage=sprintf('%d errors writing TPOs to PM',writeError);
            app.TopMessage(dmessage);
            end
        end

        % Button pushed function: PMODSaveButton
        function PMODSaveButtonPushed(app, event)
             resultsave=app.nnp.saveOD(7); %true or false
            if  resultsave
                app.TopMessage('PM OD saved');
            else
                app.TopMessage('Error saving PM OD');
            end
        end

        % Button pushed function: WritePGTempAccTPDOsButton
        function WritePGTempAccTPDOsButtonPushed(app, event)
            %Write TPDOs to send back Accelerometers, temperature and current
            
            app.PGnodelist=str2num(cell2mat(app.NodeListStim.Value)); %at some point I want to read this from the calling function.  Assume 1-6 for now
            %Since I am writing these in different commands, I can reuse teh TPDO structure
            app.TPDO.mapping = uint32(hex2dec(app.PGTpdomapping.Data));
            % 0x20 = 32 bits= 4 bytes for Acc data
            % if revision number is <167, then need first index to be uint32(hex2dec('2011101200')) which is unfiltered
            % 0x10 = 16 bits for temperature data
            app.TPDO.id = uint32(hex2dec('00000180'));
            % TPDO.type = uint8(1); %sent with every sync, regardless if data has changed
            % TPDO.type = uint8(0);  %sent with every sync, only if data has changed
            app.TPDO.type = uint8(253);  %disabled - will enable as needed
            app.TPDO.inhibit = uint16(0); %not relevant for synchronous
            app.TPDO.compat = uint8(0); %not relevant for RMs
            app.TPDO.timer = uint16(0); %not relevant for synchronous
            
            app.TopMessage('Writing RM TPDOs...');
           

             numnodes=length(app.PGnodelist);
             if app.WriteSwitch.Value=='One'
                 maxCol=length(app.PGnodelist);
                 numnodes=1;
             end

             for i=1:numnodes
                 if app.WriteSwitch.Value=='One'
                     a=app.ColEditField.Value;
                     if a>=1&&a<=maxCol % check for range
                         ix=a; % should check here to make sure in valid range
                     else
                         %nx=1; %set column 1 if out of range -  could just put return
                         app.TopMessage('Column outside of range');
                         return
                     end
                 else
                     ix=i;
                 end

              currentNode=app.PGnodelist(ix); % for error messages

                % get module revision number
                resp=double(app.nnp.read(currentNode,'1018',3,'uint32')); % read revision number of node
                if ~isempty(resp)
                    pgversion=resp;
                    vermessage=sprintf('PG firmware version %d',pgversion);
                    app.TopMessage(vermessage);
                    if pgversion<167 % no filtering before 167
                        % same as turning filtering switch off, but does this automatically on for each node
                        app.TPDO.mapping(1)=uint32(hex2dec('20110120')); % force correct mapping for this revision of firmware
                   %     app.PGTpdomapping.Data{1}='20110120'; % Force correct default value into table
                    end
                   writeError=0; %start with no write errors
                    % NEED TO:
                    % - check status of app.FilteredAccelerometersSwitch.
                    if app.FilteredAccelerometersSwitch.Value == "Off"
                        % - If status is 'no', then need to set the filter
                        % depth to 0, which disables 2011.2, thereby saving
                        % processing time.
                        result=app.nnp.write(currentNode, '2012', 1, 0, 'uint8'); %disable 2011.2
                        if result~=0
                        writeError=writeError+1;
                        end
                    end

                    %BlockWrite isnt implemented at CAN level.
                    for j =1:8
                        result=app.nnp.write(currentNode, '1A00', j, app.TPDO.mapping(j), 'uint32');
                        if result~=0
                            writeError=writeError+1;
                        end
                    end
                    result=app.nnp.write(currentNode, '1800', 1, app.TPDO.id+app.PGnodelist(i), 'uint32');
                    if result~=0
                        writeError=writeError+1;
                    end
                    result=app.nnp.write(currentNode, '1800', 2, app.TPDO.type, 'uint8');
                    if result~=0
                        writeError=writeError+1;
                    end
                    result=app.nnp.write(currentNode, '1800', 3, app.TPDO.inhibit, 'uint16');
                    if result~=0
                        writeError=writeError+1;
                    end
                    result=app.nnp.write(currentNode, '1800', 4, app.TPDO.compat, 'uint8');
                    if result~=0
                        writeError=writeError+1;
                    end
                    result=app.nnp.write(currentNode, '1800', 5, app.TPDO.timer, 'uint16');
                    if result~=0
                        writeError=writeError+1;
                    end
                    dmessage=sprintf('%d errors writing TPOs to Node %d',writeError,currentNode);
                    app.TopMessage(dmessage);
                    pause(.2);
                    % save to OD
                    % should only save if there are no errors
                    if writeError==0
                        resultsave=app.nnp.saveOD(app.PGnodelist(i)); % save to OD
                        if  resultsave
                            dmessage=sprintf('Saved OD node %d',currentNode);
                        else
                            dmessage=sprintf('Error saving OD node %d',currentNode);
                        end
                        app.TopMessage(dmessage);
                        pause(.25);
                    end
                else
                    vermessage=sprintf('Could not read PG %d version number',i);
                    app.TopMessage(vermessage);
                end
            end
            app.PowerCycleNetwork(); % power cycle network to make changes take effect
        end

        % Button pushed function: PGTestYPDOsButton
        function PGTestYPDOsButtonPushed(app, event)
            app.PGnodelist=str2num(cell2mat(app.NodeListStim.Value));
            app.RPDO.mapping = cell(8,1);
            app.RPDO.mapping = uint32(hex2dec(app.PGRpdomapping.Data));
            app.RPDO.id = uint32(hex2dec(app.PGRpdoID.Data));
            app.RPDO.type=uint8(255);
            app.RPDO.inhibit=uint16(0);
            app.RPDO.compat=uint8(0);
            app.RPDO.timer=uint16(0);
            
            app.TopMessage('Writing PG TestY RPDOs to PG nodes...');

        % a quick way to be able to write a single column but
                % maintain the entire node table.  I probably should just
                % give and error and not write anything of column out of
                % range.
                numnodes=length(app.PGnodelist);
                if app.WriteSwitch.Value=='One'
                    maxCol=length(app.PGnodelist);
                    numnodes=1;
                end
                for i=1:numnodes
                    if app.WriteSwitch.Value=='One'
                        a=app.ColEditField.Value;
                        if a>=1&&a<=maxCol % check for range
                            ix=a; % should check here to make sure in valid range
                        else
                            %nx=1; %set column 1 if out of range -  could just put return
                            app.TopMessage('Column outside of range');
                            return
                        end
                    else
                        ix=i;
                    end

                    currentNode=app.PGnodelist(ix); % for error messages
                    writeError=0; %start with no write errors

                    result=app.nnp.write(currentNode, '1400', 1, app.RPDO.id(ix), 'uint32');
                    if result~=0
                        writeError=writeError+1;
                    end
                    result=app.nnp.write(currentNode, '1400', 2, app.RPDO.type, 'uint8');
                    if result~=0
                        writeError=writeError+1;
                    end
                    result=app.nnp.write(currentNode, '1400', 3, app.RPDO.inhibit, 'uint16');
                    if result~=0
                        writeError=writeError+1;
                    end
                    result=app.nnp.write(currentNode, '1400', 4, app.RPDO.compat, 'uint8');
                    if result~=0
                        writeError=writeError+1;
                    end
                    result=app.nnp.write(currentNode, '1400', 5, app.RPDO.timer, 'uint16');
                    if result~=0
                        writeError=writeError+1;
                    end
                    for j=1:8
                        result=app.nnp.write(currentNode,'1600',j,app.RPDO.mapping(j),'uint32');
                        if result~=0
                            writeError=writeError+1;
                        end
                    end
                    dmessage=sprintf('%d errors writing TestY RPOs to Node %d',writeError,currentNode);
                    app.TopMessage(dmessage);
                    pause(.4);

                    % save to OD
                    if writeError==0
                        resultsave=app.nnp.saveOD(currentNode); % save to OD
                        if  resultsave
                            dmessage=sprintf('Saved OD node %d',currentNode);
                        else
                            dmessage=sprintf('Error saving OD node %d',currentNode);
                        end
                        app.TopMessage(dmessage);
                        pause(.2);
                    end
                end
                app.PowerCycleNetwork(); % Power cycle network to make changes take effect
            
        end

        % Button pushed function: ResetTPDOTabletoDefaultButton
        function ResetTPDOTabletoDefaultButtonPushed(app, event)
            app.defaultTPDOpmSettings();
            app.defaultTPDOpmMapping();
        end

        % Button pushed function: PMVersionButton
        function PMVersionButtonPushed(app, event)
            app.getPMversion();
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            resp = questdlg('You may have unsaved changes.  Do you want to continue with a new file?');
            if ~isequal(resp, 'Yes')
                return;
            end
            % open file and load into tables and variables
            if isfield(app.userFolders,'config')&& ~isempty(app.userFolders.config)
                    fname=[app.userFolders.code,app.defaultSuffix];
                    defName=fullfile(app.userFolders.config,fname); % name with path                  
            else
                defName=app.defaultName; % name without path
            end
			[filename, filepath] = uigetfile('.mat', 'Select PDO Configuration File',defName); % Exercise parameter file

            if filename
                app.OpenFileByName(filename,filepath);
            end

           %{
            if filename
                ld = load([filepath filename]);
                TempFile = [filepath filename];  % save filename as temporary until the file is checked
                
            else
                return;
            end
            fields = {'TPDOsettingsTableData', 'PGRpdoIDData', 'PGRpdomappingData', 'PGTpdomappingData', ...
                'RPDO', 'RPDOmapTableData', 'RPDOsettingsTableData', 'TPDO', 'TPDOmapTableData', 'NodeListStimValue'};
            iMissing = find(~isfield(ld, fields));
            
            str = [];
            for i=1:length(iMissing)
                str = [str ', ' fields{iMissing(i)}];
            end
            if ~isempty(str)
                warndlg(['Invalid NNP System File.  Does not contain variable(s): ' str '.'], 'Open File')
                return;
            end
            %               If the file is invalid, we don't want to update anything, including the file name
            app.RPDO=ld.RPDO;
            app.RPDOsettingsTable.Data=ld.RPDOsettingsTableData;
            app.RPDOmapTable.Data=ld.RPDOmapTableData;
            app.TPDO=ld.TPDO;
            app.TPDOsettingsTable.Data=ld.TPDOsettingsTableData;
            app.TPDOmapTable.Data=ld.TPDOmapTableData;
            app.NodeListStim.Value=ld.NodeListStimValue;
            app.PGRpdoID.Data=ld.PGRpdoIDData;
            app.PGRpdomapping.Data=ld.PGRpdomappingData;
            app.PGTpdomapping.Data=ld.PGTpdomappingData;
            wflLogical=app.RPDOmapTable.Data{2,1}; % normal = '1F532E08' reversed = '1F532F08'
            if strcmpi(wflLogical,'1F532E08')
                % make switch normal
                app.PhysicalEMGConnectionsSwitch.Value = 'Normal';
            else
                %make switch reversed
                app.PhysicalEMGConnectionsSwitch.Value = 'Reversed';
            end
           %}
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            if isempty(app.saveFile)
                app.SaveAsMenuSelected([])
            else
                RPDO=app.RPDO;
                RPDOsettingsTableData=app.RPDOsettingsTable.Data;
                RPDOmapTableData =app.RPDOmapTable.Data;
                TPDO=app.TPDO;
                TPDOsettingsTableData=app.TPDOsettingsTable.Data;
                TPDOmapTableData=app.TPDOmapTable.Data;
                NodeListStimValue=app.NodeListStim.Value;
                PGRpdoIDData=app.PGRpdoID.Data;
                PGRpdomappingData=app.PGRpdomapping.Data;
                PGTpdomappingData=app.PGTpdomapping.Data;
                BPTpdomappingData=app.BPTpdomapping.Data;
                save(app.saveFile, 'TPDOsettingsTableData', 'PGRpdoIDData', 'PGRpdomappingData', 'PGTpdomappingData', 'BPTpdomappingData', ...
                'RPDO', 'RPDOmapTableData', 'RPDOsettingsTableData', 'TPDO', 'TPDOmapTableData', 'NodeListStimValue');
            end            
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
             defName=fullfile(app.userFolders.config,app.defaultName);
            [filename, filepath] = uiputfile('.mat', 'Save PDO Configuration File As...',defName);
            if filename
                app.saveFile = [filepath filename];
                app.SaveMenuSelected([]);
            end            
        end

        % Value changed function: FilteredAccelerometersSwitch
        function FilteredAccelerometersSwitchValueChanged(app, event)
            app.defaultTPDOpgmapping(); % the function uses the value from the switch
        end

        % Button pushed function: WriteBPTempAccTPDOsButton
        function WriteBPTempAccTPDOsButtonPushed(app, event)
            %Write TPDOs to send back Accelerometers, temperature and current
            app.BPnodelist=str2num(cell2mat(app.NodeListBP.Value)); %at some point I want to read this from the calling function.  Assume 1-6 for now
            %Since I am writing these in different commands, I can reuse teh TPDO structure
            app.TPDO.mapping = uint32(hex2dec(app.BPTpdomapping.Data));
            % 0x20 = 32 bits= 4 bytes for Acc data
            % if revision number is <135, then need first index to be uint32(hex2dec('2011101200')) which is unfiltered
            % 0x10 = 16 bits for temperature data
            app.TPDO.id = uint32(hex2dec('00000180'));
            % TPDO.type = uint8(1); %sent with every sync, regardless if data has changed
            % TPDO.type = uint8(0);  %sent with every sync, only if data has changed
            app.TPDO.type = uint8(253);  %disabled - will enable as needed
            app.TPDO.inhibit = uint16(0); %not relevant for synchronous
            app.TPDO.compat = uint8(0); %not relevant for RMs
            app.TPDO.timer = uint16(0); %not relevant for synchronous
            
            app.TopMessage('Writing BP Temp Acc TPDOs...');
            
            for i=1:length(app.BPnodelist)
                %BlockWrite isnt implemented at CAN level.
                writeError=0; %start with no write errors
                currentNode=app.BPnodelist(i); % for error messages
                % get module revision number
                resp=double(app.nnp.read(currentNode,'1018',3,'uint32')); % read revision number of node
                if ~isempty(resp)
                    bpversion=resp;
                    vermessage=sprintf('BP firmware version %d',bpversion);
                     app.TopMessage(vermessage);
                    if bpversion<135
                        app.TPDO.mapping(1)=uint32(hex2dec('20110120')); % force correct mapping for this revision of firmware
                        app.BPTpdomapping.Data{1}='20110120'; % Force correct default value into table
                    end
                    for j =1:8
                        result=app.nnp.write(app.BPnodelist(i), '1A00', j, app.TPDO.mapping(j), 'uint32');
                        if result~=0
                            writeError=writeError+1;
                        end
                    end
                    
                    result=app.nnp.write(app.BPnodelist(i), '1800', 1, app.TPDO.id+app.BPnodelist(i), 'uint32');
                    if result~=0
                        writeError=writeError+1;
                    end
                    result=app.nnp.write(app.BPnodelist(i), '1800', 2, app.TPDO.type, 'uint8');
                    if result~=0
                        writeError=writeError+1;
                    end
                    result=app.nnp.write(app.BPnodelist(i), '1800', 3, app.TPDO.inhibit, 'uint16');
                    if result~=0
                        writeError=writeError+1;
                    end
                    result=app.nnp.write(app.BPnodelist(i), '1800', 4, app.TPDO.compat, 'uint8');
                    if result~=0
                        writeError=writeError+1;
                    end
                    result=app.nnp.write(app.BPnodelist(i), '1800', 5, app.TPDO.timer, 'uint16');
                    if result~=0
                        writeError=writeError+1;
                    end
                    dmessage=sprintf('%d errors writing BP Temp Acc TPDOs to Node %d',writeError,currentNode);
                    app.TopMessage(dmessage);
                    pause(.2);
                    
                    % save to OD
                    if writeError==0
                        resultsave=app.nnp.saveOD(app.BPnodelist(i)); % save to OD
                        if  resultsave
                            dmessage=sprintf('Saved OD node %d',currentNode);
                            app.PowerCycleNetwork();
                        else
                            dmessage=sprintf('Error saving OD node %d',currentNode);
                        end
                        app.TopMessage(dmessage);
                    end
                else
                    app.TopMessage('Could not read BP version number');
                end
            end
             
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ishandle(app.callingFigure) % check that is is valid
                figure(app.callingFigure); % bring to front
            end
            delete(app)
            
        end

        % Value changed function: PhysicalEMGConnectionsSwitch
        function PhysicalEMGConnectionsSwitchValueChanged(app, event)
            value = app.PhysicalEMGConnectionsSwitch.Value;
            % Here I need to swap the routings to 1F53.2E and 2F as well as
            % 1F53.A2 and A3
            % Note also that the standard configuration of rpdos does not
            % route to 1F53.A0 or A1, but rather to 2E and 2F directly
            % This method is clearly less efficient - but is a quick
            % solution for now:
            if strcmpi(value,'Reversed')
                %reversed swap rows 1-2 with 5-6 in column 1
                app.RPDOmapTable.Data=...
               {'1F53A308','1F53C008','1F53A808','1F53AC08','1F53B008','1F53B408','1F53B808','1F53BC08';...
                '1F532F08','1F53C108','1F53A908','1F53AD08','1F53B108','1F53B508','1F53B908','1F53BD08';...
                '1F53A408','1F53C208','1F53AA08','1F53AE08','1F53B208','1F53B608','1F53BA08','1F53BE08';...
                '1F53A508','1F53C308','1F53AB08','1F53AF08','1F53B308','1F53B708','1F53BB08','1F53BF08';...
                '1F53A208','1F53E208','1F53D608','1F53D808','1F53DA08','1F53DC08','1F53DE08','1F53E008';...
                '1F532E08','1F53E308','1F53D708','1F53D908','1F53DB08','1F53DD08','1F53DF08','1F53E108';...
                '1F53A608','1F53CA08','1F53C408','1F53C508','1F53C608','1F53C708','1F53C808','1F53C908';...
                '1F53A708','1F53D108','1F53CB08','1F53CC08','1F53CD08','1F53CE08','1F53CF08','1F53D008'};
            else
                %normal
                app.RPDOmapTable.Data=...
               {'1F53A208','1F53C008','1F53A808','1F53AC08','1F53B008','1F53B408','1F53B808','1F53BC08';...
                '1F532E08','1F53C108','1F53A908','1F53AD08','1F53B108','1F53B508','1F53B908','1F53BD08';...
                '1F53A408','1F53C208','1F53AA08','1F53AE08','1F53B208','1F53B608','1F53BA08','1F53BE08';...
                '1F53A508','1F53C308','1F53AB08','1F53AF08','1F53B308','1F53B708','1F53BB08','1F53BF08';...
                '1F53A308','1F53E208','1F53D608','1F53D808','1F53DA08','1F53DC08','1F53DE08','1F53E008';...
                '1F532F08','1F53E308','1F53D708','1F53D908','1F53DB08','1F53DD08','1F53DF08','1F53E108';...
                '1F53A608','1F53CA08','1F53C408','1F53C508','1F53C608','1F53C708','1F53C808','1F53C908';...
                '1F53A708','1F53D108','1F53CB08','1F53CC08','1F53CD08','1F53CE08','1F53CF08','1F53D008'};
            end
            app.RPDO.mapping = cell(8,1);
            for i=1:8
                app.RPDO.mapping{i}=uint32(hex2dec(app.RPDOmapTable.Data(:,i)))'; %Convert one column at a time
            end    
            
            % the more efficient way would have been to write the uint32
            % values directly to the data table.  But here we have all the
            % values in nice, neat tables, in hex which is easier to see
            % the locations
        end

        % Button pushed function: AccSetupButton
        function AccSetupButtonPushed(app, event)
            accSettings(app.nnp,app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'PDOcfg';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Text = 'SaveAs';
            app.ResetRPDOTabletoDefaultButton = uibutton(app.UIFigure, 'push');
            app.ResetRPDOTabletoDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @ResetRPDOTabletoDefaultButtonPushed, true);
            app.ResetRPDOTabletoDefaultButton.Tooltip = {'Set tables back to default values '};
            app.ResetRPDOTabletoDefaultButton.Text = {'Reset RPDO'; 'Table to '; 'Default'};
            app.WritePMRPDOButton = uibutton(app.UIFigure, 'push');
            app.WritePMRPDOButton.ButtonPushedFcn = createCallbackFcn(app, @WritePMRPDOButtonPushed, true);
            app.WritePMRPDOButton.Text = {'Write PM'; 'RPDO'};
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RPDOPMTab = uitab(app.TabGroup);
            app.RPDOPMTab.Title = 'RPDO-PM';
            app.RPDOmapTable = uitable(app.RPDOPMTab);
            app.RPDOmapTable.ColumnName = {'PDO1'; 'PDO2'; 'PDO3'; 'PDO4'; 'PDO5'; 'PDO6'; 'PDO7'; 'PDO8'};
            app.RPDOmapTable.RowName = {};
            app.RPDOmapTable.ColumnEditable = true;
            app.x1600MappingLabel = uilabel(app.RPDOPMTab);
            app.x1600MappingLabel.Text = '0x1600 - Mapping';
            app.RPDOsettingsTable = uitable(app.RPDOPMTab);
            app.RPDOsettingsTable.ColumnName = {'PDO1'; 'PDO2'; 'PDO3'; 'PDO4'; 'PDO5'; 'PDO6'; 'PDO7'; 'PDO8'};
            app.RPDOsettingsTable.RowName = {};
            app.RPDOsettingsTable.ColumnEditable = true;
            app.x1400SettingsLabel = uilabel(app.RPDOPMTab);
            app.x1400SettingsLabel.Text = '0x1400 - Settings';
            app.PMRPDOLabel = uilabel(app.RPDOPMTab);
            app.PMRPDOLabel.Text = 'PM RPDO';
            app.NotethatPDO1isdatareturnedfromtheBPmoduleLabel = uilabel(app.RPDOPMTab);
            app.NotethatPDO1isdatareturnedfromtheBPmoduleLabel.Text = {'Note that PDO1 is data returned from the BP module. The standard connection would be the binary logic signal coming from  channel  1. '; 'Use the switch to swap the routing of channels if the phyical EMG channels are reversed.  Physical channel 1 is then routed to MES2 in OD '};
            app.PhysicalEMGConnectionsSwitchLabel = uilabel(app.RPDOPMTab);
            app.PhysicalEMGConnectionsSwitchLabel.Text = 'Physical EMG Connections';
            app.PhysicalEMGConnectionsSwitch = uiswitch(app.RPDOPMTab, 'slider');
            app.PhysicalEMGConnectionsSwitch.Items = {'Normal', 'Reversed'};
            app.PhysicalEMGConnectionsSwitch.ValueChangedFcn = createCallbackFcn(app, @PhysicalEMGConnectionsSwitchValueChanged, true);
            app.PhysicalEMGConnectionsSwitch.Tooltip = {'swap PDO1 mapping rows 1-2 with 5-6'};
            app.PhysicalEMGConnectionsSwitch.Value = 'Normal';
            app.IndexSubindexTotalBits422byteinHexLabel = uilabel(app.RPDOPMTab);
            app.IndexSubindexTotalBits422byteinHexLabel.Text = {'(Index)(Subindex)(Total Bits)'; '(4)(2)(2) byte in Hex'; ''};
            app.TPDOPMTab = uitab(app.TabGroup);
            app.TPDOPMTab.Title = 'TPDO-PM';
            app.TPDOmapTable = uitable(app.TPDOPMTab);
            app.TPDOmapTable.ColumnName = {'PDO1'; 'PDO2'; 'PDO3'; 'PDO4'; 'PDO5'; 'PDO6'; 'PDO7'; 'PDO8'};
            app.TPDOmapTable.RowName = {};
            app.TPDOmapTable.ColumnEditable = true;
            app.x1A00MappingLabel = uilabel(app.TPDOPMTab);
            app.x1A00MappingLabel.Text = '0x1A00 - Mapping';
            app.TPDOsettingsTable = uitable(app.TPDOPMTab);
            app.TPDOsettingsTable.ColumnName = {'PDO1'; 'PDO2'; 'PDO3'; 'PDO4'; 'PDO5'; 'PDO6'; 'PDO7'; 'PDO8'};
            app.TPDOsettingsTable.RowName = {};
            app.TPDOsettingsTable.ColumnEditable = true;
            app.x1800SettingsLabel = uilabel(app.TPDOPMTab);
            app.x1800SettingsLabel.Text = '0x1800 - Settings';
            app.PMTPDOLabel = uilabel(app.TPDOPMTab);
            app.PMTPDOLabel.Text = 'PM TPDO';
            app.IndexSubindexTotalBits422byteinHexLabel_2 = uilabel(app.TPDOPMTab);
            app.IndexSubindexTotalBits422byteinHexLabel_2.Text = {'(Index)(Subindex)(Total Bits)'; '(4)(2)(2) byte in Hex'; ''};
            app.StimTab = uitab(app.TabGroup);
            app.StimTab.Tooltip = {''};
            app.StimTab.Title = 'Stim';
            app.NodeListStim = uitextarea(app.StimTab);
            app.NodeListStim.Value = {'1 2 3 4 5 6'};
            app.StimNodesLabel = uilabel(app.StimTab);
            app.StimNodesLabel.Text = {'Stim'; 'Nodes'};
            app.TpdoTempAccPanel = uipanel(app.StimTab);
            app.TpdoTempAccPanel.Title = 'Tpdo Temp,Acc,';
            app.PGTpdomapping = uitable(app.TpdoTempAccPanel);
            app.PGTpdomapping.ColumnName = {'Mapping'};
            app.PGTpdomapping.RowName = {};
            app.PGTpdomapping.ColumnEditable = true;
            app.PGTpdomapping.Tooltip = {'Which data from the node OD should be included in the outgoing PDO from the node'};
            app.RpdoTestYPGPanel = uipanel(app.StimTab);
            app.RpdoTestYPGPanel.Title = 'Rpdo TestY PG';
            app.PGRpdomapping = uitable(app.RpdoTestYPGPanel);
            app.PGRpdomapping.ColumnName = {'Mapping'};
            app.PGRpdomapping.RowName = {};
            app.PGRpdomapping.ColumnEditable = true;
            app.PGRpdomapping.Tooltip = {'Where in the OD the data from the incoming PDO should be stored'};
            app.PGRpdoID = uitable(app.RpdoTestYPGPanel);
            app.PGRpdoID.ColumnName = {'RPDO_ID'};
            app.PGRpdoID.RowName = {};
            app.PGRpdoID.ColumnEditable = true;
            app.PGRpdoID.Tooltip = {'These correspond to the node numbers listed above in "Stim Nodes" and are the IDs of the PDOs from the PM to each node'};
            app.FilteredAccelerometersLabel = uilabel(app.StimTab);
            app.FilteredAccelerometersLabel.Text = {'Filtered'; 'Accelerometers'};
            app.FilteredAccelerometersSwitch = uiswitch(app.StimTab, 'slider');
            app.FilteredAccelerometersSwitch.ValueChangedFcn = createCallbackFcn(app, @FilteredAccelerometersSwitchValueChanged, true);
            app.FilteredAccelerometersSwitch.Tooltip = {'Determines where to get the acceleromenter data from. Sets pdo to 2011.1 or 2011.2 if filtered.  Need to set filter depth for each node at 2012.1'};
            app.NOTEThissetsallofthePGnodestousethesameaccvalueLabel = uilabel(app.StimTab);
            app.NOTEThissetsallofthePGnodestousethesameaccvalueLabel.Text = {'NOTE: This sets all of the PG nodes to use the same acc source.   '; '       They could be set individually to either filtered or unfiltered,'; '        along with the individual depth settings.   '};
            app.AccSetupButton = uibutton(app.StimTab, 'push');
            app.AccSetupButton.ButtonPushedFcn = createCallbackFcn(app, @AccSetupButtonPushed, true);
            app.AccSetupButton.Tooltip = {'Set up accelerometer settings and aux control'};
            app.AccSetupButton.Text = 'Acc Setup';
            app.WriteSwitchLabel = uilabel(app.StimTab);
            app.WriteSwitchLabel.Text = 'Write';
            app.WriteSwitch = uiswitch(app.StimTab, 'slider');
            app.WriteSwitch.Items = {'All', 'One'};
            app.WriteSwitch.Value = 'All';
            app.ColEditFieldLabel = uilabel(app.StimTab);
            app.ColEditFieldLabel.Text = 'Col';
            app.ColEditField = uieditfield(app.StimTab, 'numeric');
            app.ColEditField.Tooltip = {'Column number in node table'};
            app.ColEditField.Value = 1;
            app.MappingFormatIndexSubindexTotalBits422byteinHexLabel = uilabel(app.StimTab);
            app.MappingFormatIndexSubindexTotalBits422byteinHexLabel.Text = {'Mapping Format'; '(Index)(Subindex)(Total Bits)'; '(4)(2)(2) byte in Hex'; ''};
            app.Label_2 = uilabel(app.StimTab);
            app.Label_2.Text = 'Each stim module is allowed one transmit PDO, which is manually configured here.';
            app.BPTab = uitab(app.TabGroup);
            app.BPTab.Title = 'BP';
            app.TpdoTempAccPanelBP = uipanel(app.BPTab);
            app.TpdoTempAccPanelBP.Title = 'Tpdo Temp,Acc,';
            app.BPTpdomapping = uitable(app.TpdoTempAccPanelBP);
            app.BPTpdomapping.ColumnName = {'Mapping'};
            app.BPTpdomapping.RowName = {};
            app.BPTpdomapping.ColumnEditable = true;
            app.BPTpdomapping.Tooltip = {'Which data from the node OD should be included in the outgoing PDO from the node'};
            app.NodeListBP = uitextarea(app.BPTab);
            app.NodeListBP.Value = {'9'};
            app.BPNodesLabel = uilabel(app.BPTab);
            app.BPNodesLabel.Text = {'BP'; ' Nodes'};
            app.MappingFormatIndexSubindexTotalBits422byteinHexLabel_2 = uilabel(app.BPTab);
            app.MappingFormatIndexSubindexTotalBits422byteinHexLabel_2.Text = {'Mapping Format'; '(Index)(Subindex)(Total Bits)'; '(4)(2)(2) byte in Hex'; ''};
            app.Label = uilabel(app.BPTab);
            app.Label.Text = {'Note:'; 'The BP module automatically sends out a PDO for the EMG recording. '; ''; 'The features for that PDO are set using the Remote Module Setup window.'; ''; 'The firmware automatically configures the OD locations (mapping) for the feature that are included..'; ''; 'The BP module can have two transmit PDOs, one is the auto PDO and the second is this additional PDO which is manually configured..'};
            app.Nnode180015Label = uilabel(app.BPTab);
            app.Nnode180015Label.Text = 'N(node):1800.1-5';
            app.WritePMTPDOsButton = uibutton(app.UIFigure, 'push');
            app.WritePMTPDOsButton.ButtonPushedFcn = createCallbackFcn(app, @WritePMTPDOsButtonPushed, true);
            app.WritePMTPDOsButton.Tooltip = {'Write n Tpdos to PM'};
            app.WritePMTPDOsButton.Text = {'Write PM'; 'TPDOs'};
            app.PMODSaveButton = uibutton(app.UIFigure, 'push');
            app.PMODSaveButton.ButtonPushedFcn = createCallbackFcn(app, @PMODSaveButtonPushed, true);
            app.PMODSaveButton.Tooltip = {'Save PM OD values to retsore'};
            app.PMODSaveButton.Text = 'PM OD Save';
            app.WritePGTempAccTPDOsButton = uibutton(app.UIFigure, 'push');
            app.WritePGTempAccTPDOsButton.ButtonPushedFcn = createCallbackFcn(app, @WritePGTempAccTPDOsButtonPushed, true);
            app.WritePGTempAccTPDOsButton.Tooltip = {'Write stim module TPDOs based on stmi nodes listed'};
            app.WritePGTempAccTPDOsButton.Text = {'Write PG'; 'Temp Acc'; 'TPDOs'};
            app.PGTestYPDOsButton = uibutton(app.UIFigure, 'push');
            app.PGTestYPDOsButton.ButtonPushedFcn = createCallbackFcn(app, @PGTestYPDOsButtonPushed, true);
            app.PGTestYPDOsButton.Tooltip = {'Write stim module TestY RPDOs based on stmi nodes listed'};
            app.PGTestYPDOsButton.Text = {'Write PG'; 'TestY'; 'RPDOs'};
            app.PMversionPanel = uipanel(app.UIFigure);
            app.PMversionPanel.Title = 'PM version';
            app.TpdosEditFieldLabel = uilabel(app.PMversionPanel);
            app.TpdosEditFieldLabel.Text = 'Tpdos';
            app.TpdosEditField = uieditfield(app.PMversionPanel, 'numeric');
            app.TpdosEditField.Editable = 'off';
            app.TpdosEditField.Tooltip = {'Number of  TPDOs to write, based on PM version.  After version 405 only 6 tPDOs are allowed.'};
            app.TpdosEditField.Value = 6;
            app.PMVersionButton = uibutton(app.PMversionPanel, 'push');
            app.PMVersionButton.ButtonPushedFcn = createCallbackFcn(app, @PMVersionButtonPushed, true);
            app.PMVersionButton.Tooltip = {'Read PM version'};
            app.PMVersionButton.Text = {'PM'; 'Version'};
            app.PMverEditFieldLabel = uilabel(app.PMversionPanel);
            app.PMverEditFieldLabel.Text = 'PMver';
            app.PMverEditField = uieditfield(app.PMversionPanel, 'numeric');
            app.ResetTPDOTabletoDefaultButton = uibutton(app.UIFigure, 'push');
            app.ResetTPDOTabletoDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @ResetTPDOTabletoDefaultButtonPushed, true);
            app.ResetTPDOTabletoDefaultButton.Tooltip = {'Set table back to default values'};
            app.ResetTPDOTabletoDefaultButton.Text = {'Reset TPDO'; 'Table to '; 'Default'};
            app.MessagesTextAreaLabel = uilabel(app.UIFigure);
            app.MessagesTextAreaLabel.Text = 'Messages';
            app.Messages = uitextarea(app.UIFigure);
            app.WriteBPTempAccTPDOsButton = uibutton(app.UIFigure, 'push');
            app.WriteBPTempAccTPDOsButton.ButtonPushedFcn = createCallbackFcn(app, @WriteBPTempAccTPDOsButtonPushed, true);
            app.WriteBPTempAccTPDOsButton.Tooltip = {'Write stim module TPDOs based on stmi nodes listed'};
            app.WriteBPTempAccTPDOsButton.Text = {'Write BP'; 'Temp Acc'; 'TPDOs'};
            app.UserIDEditField = uieditfield(app.UIFigure, 'text');
            app.UserIDEditField.Editable = 'off';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = {'Note: '; 'Use PDO Setup Read app to verify that the changes have been made and saved.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pdoConfig(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end