classdef pmReadRestoreLog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UserIDLabel                 matlab.ui.control.Label
        UserIDEditField             matlab.ui.control.EditField
        OtherHelpfulDataPanel       matlab.ui.container.Panel
        RemoteModuleCurrentConfigurationButton  matlab.ui.control.Button
        RestartScriptsPanel         matlab.ui.container.Panel
        WOROnButton                 matlab.ui.control.Button
        StartScriptsButton          matlab.ui.control.Button
        ResetLogSpacePanel          matlab.ui.container.Panel
        SaveODButton                matlab.ui.control.Button
        BlankCheckButton            matlab.ui.control.Button
        CntEditField                matlab.ui.control.NumericEditField
        CntEditFieldLabel           matlab.ui.control.Label
        FlushLogButton              matlab.ui.control.Button
        ReadDataPanel               matlab.ui.container.Panel
        ViewLogButton               matlab.ui.control.Button
        ApproxTimeMinutesEditField  matlab.ui.control.NumericEditField
        ApproxTimeMinutesEditFieldLabel  matlab.ui.control.Label
        ReadPMLogButton             matlab.ui.control.Button
        ReadRestoreButton           matlab.ui.control.Button
        PreparationPanel            matlab.ui.container.Panel
        TestConnectionButton        matlab.ui.control.Button
        RadioConfigButton           matlab.ui.control.Button
        DisableScriptsButton        matlab.ui.control.Button
        WOROffButton                matlab.ui.control.Button
        LogFileNameLabel            matlab.ui.control.Label
        RestoreFileNameLabel        matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        MessagesTextArea            matlab.ui.control.TextArea
        MessagesTextAreaLabel       matlab.ui.control.Label
        AssumethatyouhavealreadyrunnnpNNPAPIandpassednnptothisappLabel  matlab.ui.control.Label
        FolderEditField             matlab.ui.control.EditField
        FolderButton                matlab.ui.control.Button
        ReadPowerModuleRestoreandLogFilesLabel  matlab.ui.control.Label
        RestoreFilenameEditField    matlab.ui.control.EditField
        LogFilenameEditField        matlab.ui.control.EditField
    end

    
    properties (Access = public)
        nnp=[];
        selpath=pwd;% get the current working directory
        RestoreFileName='TempRestoreFileName.bin'; % Save this to a temporary file
        RFileID=[]; % File ID for Retore File
        LogFileName='TempLogFileName.bin'; % Save this to a temporary file
        LFileID=[]; % File ID for Log File
        callingFigure=[]; %handle to calling figure
        % Code for auto userID open
         userFolders=struct;  % userfiles, code, user, config, data, log, where code is file prefix
         defaultRestoreSuffix='_PMRestore'; %before all the date/time information is added
         defaultLogSuffix='_PMLog'; %before all the date/time information is added
         defaultRestoreName='P00_RMRestore'; %if userFolders is not valid use this
         defaultLogName='P00_RMLog'; %if userFolders is not valid use this
         defaultPath=pwd;
    end
    
    methods (Access = public)
        
        function FillFileNameFields(app)
            timestring=sprintf('_%02.0f',datevec(now));
            app.RestoreFilenameEditField
            app.RestoreFilenameEditField.Value=[app.defaultRestoreName,timestring,'.bin'];
            app.RestoreFileName=fullfile(app.FolderEditField.Value,app.RestoreFilenameEditField.Value);      
            timestring=sprintf('_%02.0f',datevec(now));
            app.LogFilenameEditField.Value=[app.defaultLogName,timestring,'.txt'];
            app.LogFileName=fullfile(app.FolderEditField.Value,app.LogFilenameEditField.Value);      
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin<2
                NNP=[];
            else
                NNP=varargin{1};
            end
            useNNP=true;
            if isempty(NNP)  || ~isvalid(NNP) %if being called after nnp cancelled from another app
                app.nnp = NNPAPI();
                if isempty(app.nnp.port)
                    uialert(app.UIFigure,'No port chosen for NNP Access Point','No NNP AP')
                    delete(app.nnp)
                    useNNP=false;
%                     return
                end
            else
                app.nnp = NNP;
            end
            if nargin>2
                app.callingFigure=varargin{2};
            end
            if nargin>3  % third is userFolders
                app.userFolders=varargin{3};  % userfiles, code, user, config, data, log
                if isfield(app.userFolders,'code')&& ~isempty(app.userFolders.code)
                    app.UserIDEditField.Value=app.userFolders.code;
                end
                if isfield(app.userFolders,'log')&& ~isempty(app.userFolders.log)
                    app.defaultRestoreName=[app.userFolders.code,app.defaultRestoreSuffix]; % name without extension
                    app.defaultLogName=[app.userFolders.code,app.defaultLogSuffix]; % name without extension
                    app.defaultPath=app.userFolders.log;
                    app.FolderEditField.Value=app.defaultPath;
                    app.FillFileNameFields();
                end   
            end
                        
        end

        % Button pushed function: FolderButton
        function FolderButtonPushed(app, event)
            %
            newpath = uigetdir(app.defaultPath,'Folder to store Log files');
            if ischar(newpath) % if canceled, this is a number 0
                if isfolder(newpath)  % can only use this with character array
                    app.defaultPath=newpath;  % update the path if a new one given
                    app.FolderEditField.Value=app.defaultPath;
                    app.FolderButton.BackgroundColor=[0.96,0.96,0.96]; % from [1,.9,.6]
                    % Create or open a comment file to add comments
                    % Create the file name but don't open until the comment
                    % is added, then append.
                    % app.NewCommentFileName(); 
                end
            end
            figure(app.UIFigure); % Restore focus to this window
            %   
            app.FillFileNameFields();
            
           
        end

        % Button pushed function: RadioConfigButton
        function RadioConfigButtonPushed(app, event)
            radioconfig(app.nnp);
        end

        % Button pushed function: TestConnectionButton
        function TestConnectionButtonPushed(app, event)
            modePM=app.nnp.read(7,'2010',1, 'uint8'); % this should produce an array that I need to convert to string
           if length(modePM)<4
               stA='Error reading PM status';
           else
            stA=sprintf(' %d ',modePM);
             stA=['PM Status Bytes:',stA];
           end
           app.MessagesTextArea.Value=stA;% for now there is no way to go to bottom programatically, so don't append
        end

        % Button pushed function: WOROffButton
        function WOROffButtonPushed(app, event)
            app.nnp.worOff;
            dmessage='WOR Off';
            app.MessagesTextArea.Value=dmessage;
        end

        % Button pushed function: WOROnButton
        function WOROnButtonPushed(app, event)
            app.nnp.worOn(20);
            dmessage='WOR On';
            app.MessagesTextArea.Value=dmessage;
        end

        % Button pushed function: DisableScriptsButton
        function DisableScriptsButtonPushed(app, event)
            app.nnp.enterWaiting();
            app.nnp.networkOff();
            app.nnp.nmt(7, '8F'); %disable scripts
            dmessage='Scripts Disabled';
            app.MessagesTextArea.Value=dmessage;            
        end

        % Button pushed function: StartScriptsButton
        function StartScriptsButtonPushed(app, event)
            app.nnp.nmt(7, '8E'); %enable scripts
            app.nnp.nmt(7,'B3',7); % Run startup script            
            dmessage='Scripts Enabled and Startup Script Run';
            app.MessagesTextArea.Value=dmessage;
        end

        % Button pushed function: SaveODButton
        function SaveODButtonPushed(app, event)
        app.nnp.saveOD(7); 
            dmessage='PM OD Saved';
            app.MessagesTextArea.Value=dmessage;        
        end

        % Button pushed function: ReadRestoreButton
        function ReadRestoreButtonPushed(app, event)
         app.nnp.readFile('odrestore', 0, 1000, 'bin', app.RestoreFileName); %read restore file, includes path
         %app.RFileID=fopen(app.RestoreFileName); % get fileID
         dmessage='Finished reading restore file';
         app.MessagesTextArea.Value=dmessage;
        end

        % Button pushed function: ReadPMLogButton
        function ReadPMLogButtonPushed(app, event)
            % first get the log cursor for reference and calculate approx
            % time to read the log. Would be helpful to have a progress
            % indicator at some point
            LC=double(app.nnp.read(7,'a200',3,'uint32')); % get log cursor
            readlogmin=LC/60000; % approximately 1000 bytes per second
            app.ApproxTimeMinutesEditField.Value=readlogmin;
            app.nnp.readFile('log', 0, 'all', 'ascii', app.LogFileName); %read log file, includes path
            dmessage='Finished reading log file';
            app.MessagesTextArea.Value=dmessage;
        end

        % Button pushed function: FlushLogButton
        function FlushLogButtonPushed(app, event)
            cnt=double(app.nnp.read(7,'2010',18,'uint16')); % first read the cnt to see where we are
            app.CntEditField.Value=cnt; %display current count
            app.nnp.flushLog(); % flush the log
            dmessage='Flushing...';
            app.MessagesTextArea.Value=dmessage;   
            % set up a loop to read the count every 2 seconds until it
            % reaches zero or until it has looped for N times
            i=1;
            for i=1:10
                pause(2); %check every 2 seconds
                cnt=double(app.nnp.read(7,'2010',18,'uint16')); % first read the cnt to see where we are
                app.CntEditField.Value=cnt; %display current count
                if cnt==0
                    break;
                end
            end
            dmessage='Finished flush';
            app.MessagesTextArea.Value=dmessage;   
        end

        % Button pushed function: BlankCheckButton
        function BlankCheckButtonPushed(app, event)
            app.nnp.nmt(7, 'BC'); % initiate blank check
            dmessage='Blank checking...';
            app.MessagesTextArea.Value=dmessage;               
            pause(3); % wait 3 seconds
            response = uint32(app.nnp.read(7, '2010', 19, 'uint32'));
            address = bitand(response, hex2dec('FFFFFF'));
            if bitget(response, 32)
                dmessage=sprintf('non blank value found at %06x\n', address);
            elseif bitget(response, 31)
                dmessage=sprintf('blank check error at %06x\n', address);
            else
                dmessage=sprintf('All blank\n');
            end
            app.MessagesTextArea.Value=dmessage; 
        end

        % Button pushed function: ViewLogButton
        function ViewLogButtonPushed(app, event)
        [fn, pn]=uigetfile(app.LogFileName);
            edit(fullfile(pn, fn));
        end

        % Button pushed function: RemoteModuleCurrentConfigurationButton
        function RemoteModuleCurrentConfigurationButtonPushed(app, event)
            rmParametersStatus(app.nnp,app.UIFigure,app.userFolders); %This can use all three inputs now
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ishandle(app.callingFigure) % check that is is valid
                figure(app.callingFigure); % bring to front
            end
            delete(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'pmReadRestoreLog';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LogFilenameEditField = uieditfield(app.UIFigure, 'text');
            app.LogFilenameEditField.Editable = 'off';
            app.RestoreFilenameEditField = uieditfield(app.UIFigure, 'text');
            app.RestoreFilenameEditField.Editable = 'off';
            app.ReadPowerModuleRestoreandLogFilesLabel = uilabel(app.UIFigure);
            app.ReadPowerModuleRestoreandLogFilesLabel.Text = 'Read Power Module Restore and Log Files';
            app.FolderButton = uibutton(app.UIFigure, 'push');
            app.FolderButton.ButtonPushedFcn = createCallbackFcn(app, @FolderButtonPushed, true);
            app.FolderButton.Text = 'Folder';
            app.FolderEditField = uieditfield(app.UIFigure, 'text');
            app.FolderEditField.Editable = 'off';
            app.AssumethatyouhavealreadyrunnnpNNPAPIandpassednnptothisappLabel = uilabel(app.UIFigure);
            app.AssumethatyouhavealreadyrunnnpNNPAPIandpassednnptothisappLabel.Text = 'Assume that you have already run nnp=NNPAPI and passed nnp to this app';
            app.MessagesTextAreaLabel = uilabel(app.UIFigure);
            app.MessagesTextAreaLabel.Text = 'Messages';
            app.MessagesTextArea = uitextarea(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'need to add clear button and append messages onto the string each time a new message is'; 'added to the queue. when setting scroll location is available.'};
            app.RestoreFileNameLabel = uilabel(app.UIFigure);
            app.RestoreFileNameLabel.Text = 'Restore File Name';
            app.LogFileNameLabel = uilabel(app.UIFigure);
            app.LogFileNameLabel.Text = 'Log File Name';
            app.PreparationPanel = uipanel(app.UIFigure);
            app.PreparationPanel.Title = 'Preparation';
            app.WOROffButton = uibutton(app.PreparationPanel, 'push');
            app.WOROffButton.ButtonPushedFcn = createCallbackFcn(app, @WOROffButtonPushed, true);
            app.WOROffButton.Text = {'WOR'; 'Off'};
            app.DisableScriptsButton = uibutton(app.PreparationPanel, 'push');
            app.DisableScriptsButton.ButtonPushedFcn = createCallbackFcn(app, @DisableScriptsButtonPushed, true);
            app.DisableScriptsButton.Text = {'Disable'; 'Scripts'};
            app.RadioConfigButton = uibutton(app.PreparationPanel, 'push');
            app.RadioConfigButton.ButtonPushedFcn = createCallbackFcn(app, @RadioConfigButtonPushed, true);
            app.RadioConfigButton.Text = {'Radio'; 'Config'};
            app.TestConnectionButton = uibutton(app.PreparationPanel, 'push');
            app.TestConnectionButton.ButtonPushedFcn = createCallbackFcn(app, @TestConnectionButtonPushed, true);
            app.TestConnectionButton.Tooltip = {'Read Status bytes for PM. where 3rd byte is the system mode'};
            app.TestConnectionButton.Text = {'Test'; 'Connection'};
            app.ReadDataPanel = uipanel(app.UIFigure);
            app.ReadDataPanel.Title = 'Read Data';
            app.ReadRestoreButton = uibutton(app.ReadDataPanel, 'push');
            app.ReadRestoreButton.ButtonPushedFcn = createCallbackFcn(app, @ReadRestoreButtonPushed, true);
            app.ReadRestoreButton.Text = {'Read'; 'Restore'};
            app.ReadPMLogButton = uibutton(app.ReadDataPanel, 'push');
            app.ReadPMLogButton.ButtonPushedFcn = createCallbackFcn(app, @ReadPMLogButtonPushed, true);
            app.ReadPMLogButton.Text = {'Read'; 'PM Log'};
            app.ApproxTimeMinutesEditFieldLabel = uilabel(app.ReadDataPanel);
            app.ApproxTimeMinutesEditFieldLabel.Text = {'Approx Time'; 'Minutes'};
            app.ApproxTimeMinutesEditField = uieditfield(app.ReadDataPanel, 'numeric');
            app.ApproxTimeMinutesEditField.ValueDisplayFormat = '%.1f';
            app.ViewLogButton = uibutton(app.ReadDataPanel, 'push');
            app.ViewLogButton.ButtonPushedFcn = createCallbackFcn(app, @ViewLogButtonPushed, true);
            app.ViewLogButton.Text = {'View'; 'Log'};
            app.ResetLogSpacePanel = uipanel(app.UIFigure);
            app.ResetLogSpacePanel.Title = 'Reset Log Space';
            app.FlushLogButton = uibutton(app.ResetLogSpacePanel, 'push');
            app.FlushLogButton.ButtonPushedFcn = createCallbackFcn(app, @FlushLogButtonPushed, true);
            app.FlushLogButton.Text = {'Flush'; 'Log'};
            app.CntEditFieldLabel = uilabel(app.ResetLogSpacePanel);
            app.CntEditFieldLabel.Text = 'Cnt';
            app.CntEditField = uieditfield(app.ResetLogSpacePanel, 'numeric');
            app.CntEditField.ValueDisplayFormat = '%.0f';
            app.BlankCheckButton = uibutton(app.ResetLogSpacePanel, 'push');
            app.BlankCheckButton.ButtonPushedFcn = createCallbackFcn(app, @BlankCheckButtonPushed, true);
            app.BlankCheckButton.Text = {'Blank'; 'Check'};
            app.SaveODButton = uibutton(app.ResetLogSpacePanel, 'push');
            app.SaveODButton.ButtonPushedFcn = createCallbackFcn(app, @SaveODButtonPushed, true);
            app.SaveODButton.Text = {'Save'; 'OD'};
            app.RestartScriptsPanel = uipanel(app.UIFigure);
            app.RestartScriptsPanel.Title = 'Restart Scripts';
            app.StartScriptsButton = uibutton(app.RestartScriptsPanel, 'push');
            app.StartScriptsButton.ButtonPushedFcn = createCallbackFcn(app, @StartScriptsButtonPushed, true);
            app.StartScriptsButton.Text = {'Start'; 'Scripts'};
            app.WOROnButton = uibutton(app.RestartScriptsPanel, 'push');
            app.WOROnButton.ButtonPushedFcn = createCallbackFcn(app, @WOROnButtonPushed, true);
            app.WOROnButton.Text = {'WOR'; 'On'};
            app.OtherHelpfulDataPanel = uipanel(app.UIFigure);
            app.OtherHelpfulDataPanel.Title = 'Other Helpful Data';
            app.RemoteModuleCurrentConfigurationButton = uibutton(app.OtherHelpfulDataPanel, 'push');
            app.RemoteModuleCurrentConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @RemoteModuleCurrentConfigurationButtonPushed, true);
            app.RemoteModuleCurrentConfigurationButton.Text = {'Remote Module'; 'Current Configuration'};
            app.UserIDEditField = uieditfield(app.UIFigure, 'text');
            app.UserIDEditField.Editable = 'off';
            app.UserIDLabel = uilabel(app.UIFigure);
            app.UserIDLabel.Text = 'UserID';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pmReadRestoreLog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end