classdef radioconfig < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RadioConfigurationUIFigure    matlab.ui.Figure
        HelpMenu                      matlab.ui.container.Menu
        RefreshMenu                   matlab.ui.container.Menu
        PMSerialEditField             matlab.ui.control.NumericEditField
        PMSerialEditFieldLabel        matlab.ui.control.Label
        Lamp                          matlab.ui.control.Lamp
        TestRadioCheckBox             matlab.ui.control.CheckBox
        SaveAPsettingsButton          matlab.ui.control.Button
        PMLQIEditField                matlab.ui.control.NumericEditField
        PMEditField_2Label            matlab.ui.control.Label
        APLQIEditField                matlab.ui.control.NumericEditField
        APEditField_2Label            matlab.ui.control.Label
        PMRSSIEditField               matlab.ui.control.NumericEditField
        PMEditFieldLabel              matlab.ui.control.Label
        APRSSIEditField               matlab.ui.control.NumericEditField
        APEditFieldLabel              matlab.ui.control.Label
        AccessPointSettingsPanel      matlab.ui.container.Panel
        APRadioRetriesEditField       matlab.ui.control.NumericEditField
        APRadioretriesEditFieldLabel  matlab.ui.control.Label
        APTXPowerEditField            matlab.ui.control.NumericEditField
        APTXPowerEditFieldLabel       matlab.ui.control.Label
        APUSBTimeoutEditField         matlab.ui.control.NumericEditField
        APUSBtimeoutsEditFieldLabel   matlab.ui.control.Label
        APRadioTimeoutEditField       matlab.ui.control.NumericEditField
        APRadiotimeoutEditFieldLabel  matlab.ui.control.Label
        hint                          matlab.ui.control.Label
        PowerModuleSettingsPanel      matlab.ui.container.Panel
        ApplyButton_2                 matlab.ui.control.Button
        PMTXPowerEditField            matlab.ui.control.NumericEditField
        PMTXPowerEditFieldLabel       matlab.ui.control.Label
        CommonSettingsPanel           matlab.ui.container.Panel
        IfcheckedchangeswillbeLabel   matlab.ui.control.Label
        ModifyPMCheckBox              matlab.ui.control.CheckBox
        WakeOnRadioCheckBox           matlab.ui.control.CheckBox
        ApplyButton                   matlab.ui.control.Button
        WOREditField                  matlab.ui.control.NumericEditField
        WakeintervalmsEditFieldLabel  matlab.ui.control.Label
        ChannelEditField              matlab.ui.control.NumericEditField
        ChannelEditFieldLabel         matlab.ui.control.Label
        PMAddressEditField            matlab.ui.control.NumericEditField
        PMAddressEditFieldLabel       matlab.ui.control.Label
        APAddressEditField            matlab.ui.control.NumericEditField
        APAddressEditFieldLabel       matlab.ui.control.Label
        LQIAxes                       matlab.ui.control.UIAxes
        RSSIAxes                      matlab.ui.control.UIAxes
    end


    properties (Access = public)
        nnp = []; 
        updateTimer = [];
        nSamp = [];
        plotbuf = [];
        callingFigure=[]; %handle to calling figure
               
    end

    properties (Access = private)
        savedSettings = []; 
        retryCount = 0;
    end

    methods (Access = public)
    
        function updateDisplaysCallback(app, obj, event)
            %the timer always runs while app is open, but only triggers radio queries to PM if "Test Radio" is checked
            %"Test Radio" is checked and unchecked by app automatically or by user.  
            %While "Test Radio" is checked, the RadioCOnfig App figure window is forced to stay above all other windows
            if strcmp(app.RadioConfigurationUIFigure.WindowState,'minimized')
                 app.disableTestRadio();
            end
            if app.TestRadioCheckBox.Value
                app.updateDisplays();
            else
                app.APRSSIEditField.Enable = 'off';
                app.APLQIEditField.Enable = 'off';
                app.PMRSSIEditField.Enable = 'off';
                app.PMLQIEditField.Enable = 'off';
                app.Lamp.Color = [.7 .7 .7];
                app.PMSerialEditField.Enable = 'off';
            end
            drawnow limitrate
        end

        function updateDisplays(app)           
            str = {};

            app.PMAddressEditField.BackgroundColor = [1 1 1];
            app.APAddressEditField.BackgroundColor = [1 1 1];
            app.ChannelEditField.BackgroundColor = [1 1 1];
            app.WOREditField.BackgroundColor = [1 1 1];
            app.PMTXPowerEditField.BackgroundColor = [1 1 1];

            switch app.APRadioTimeoutEditField.Value
                case 251, timeout = 1;
                case 252, timeout = 4;
                case 253, timeout = 10;
                case 254, timeout = 40;
                otherwise, timeout = app.APRadioTimeoutEditField.Value/1000;
            end
            
            recTimeout = (app.WOREditField.Value/1000 + timeout)*(app.APRadioRetriesEditField.Value+1);
            
            if app.APUSBTimeoutEditField.Value < recTimeout
                app.APUSBTimeoutEditField.BackgroundColor = [1 .7 .2];
            else
                app.APUSBTimeoutEditField.BackgroundColor = [1 1 1];
            end

            if ~app.TestRadioCheckBox.Value 
                return;  %quit early if test radio unclicked
            end
%             if isequal(app.nnp.lastError, 'AP access denied') 
%                % app.disableTestRadio();
%             end
            if ~app.TestRadioCheckBox.Value 
                return;  %quit early if test radio unclicked
            end
            resp = app.nnp.read(7, '2600', 1, 6, 'uint8'); 
            rssiAP = NaN;
            lqiAP = NaN;
            if length(resp)==6 
                
                app.APRSSIEditField.Enable = 'on';
                app.APLQIEditField.Enable = 'on';
                app.PMRSSIEditField.Enable = 'on';
                app.PMLQIEditField.Enable = 'on';
                
                
                if resp(1) ~= app.PMAddressEditField.Value
                    str{length(str)+1} =  'PM address from PM does not match AP settings';
                end
                if resp(2) ~= app.APAddressEditField.Value
                    str{length(str)+1} = 'AP address from PM does not match AP settings';
                end
                if resp(3) ~= app.ChannelEditField.Value
                    str{length(str)+1} = 'AP address from PM does not match AP settings';
                end 
                
                app.PMTXPowerEditField.Value = double(resp(4));
                
                rssioffset = 74;
                rssiraw = double(resp(5));
                lqiraw = double(resp(6));
                
                 if rssiraw < 128
                     rssi = rssiraw/2 - rssioffset;
                 else
                     rssi = (rssiraw-256)/2 - rssioffset;
                 end
                 if lqiraw >= 128
                    lqi = lqiraw - 128;
                 else
                    lqi = lqiraw; %bad CRC
                    str{length(str)+1} = 'BadCRC on PM!';
                 end
                
                app.PMRSSIEditField.Value = rssi;
                app.PMLQIEditField.Value = lqi;
                
                if ~isempty(app.nnp.RSSI) 
                    app.APRSSIEditField.Value = app.nnp.RSSI;
                    rssiAP = app.nnp.RSSI;
                end
                if ~isempty(app.nnp.LQI)
                    app.APLQIEditField.Value = app.nnp.LQI;
                    lqiAP = app.nnp.LQI;
                end

                app.Lamp.Color = [0 1 0];
                app.retryCount = 0;
            else
                app.APRSSIEditField.Enable = 'off';
                app.APLQIEditField.Enable = 'off';
                app.PMRSSIEditField.Enable = 'off';
                app.PMLQIEditField.Enable = 'off';
                
                app.Lamp.Color = [1 0 0];
                app.retryCount = app.retryCount + 1;
                if app.retryCount >= 99999
                    app.disableTestRadio();
                end
                
                rssi=NaN;
                lqi=NaN;
            end
            
            app.plotbuf(1:end-1,:) = app.plotbuf(2:end,:);
            app.plotbuf(end,:) = [rssiAP; rssi; lqiAP; lqi ];
            for i = 1:2
                app.RSSIAxes.Children(i).YData = app.plotbuf(:,i); 
                app.LQIAxes.Children(i).YData = app.plotbuf(:,i+2); 
            end 

            if ~app.TestRadioCheckBox.Value 
                return;  %quit early if test radio unclicked
            end
            resp = double(app.nnp.read(7, '2600', 9, 'uint16')); %read WOR status
            if length(resp)==1
                if resp(1) ~= app.WOREditField.Value && app.WakeOnRadioCheckBox.Value == 1
                    %str{length(str)+1} = sprintf('Wake Interval from PM is set to %u ms', resp(1));
                end
                if  resp(1) > 0 && app.WakeOnRadioCheckBox.Value == 0
                    str{length(str)+1} = 'PM has Wake On Radio enabled.  Turn on WOR on AP';
                elseif  resp(1) == 0 && app.WakeOnRadioCheckBox.Value == 1
                    str{length(str)+1} = 'PM does not have Wake On Radio enabled.  Turn off on AP for higher bandwidth';

                end
            else
                return;
            end
            
            if ~app.TestRadioCheckBox.Value 
                return; %quit early if test radio unclicked
            end
            resp = app.nnp.getSerial(7);
            if length(resp)==1
                app.PMSerialEditField.Value = resp;
                app.PMSerialEditField.Enable = 'on';
            else
                app.PMSerialEditField.Enable = 'off';
            end
            
            if ~isempty(str)
                uialert(app.RadioConfigurationUIFigure, str, 'AP+PM Configuration.', 'Icon','info')   
                app.TestRadioCheckBox.Value = false;
                 app.TestRadioCheckBoxValueChanged();
            end
            
        end
        
    end
    
    methods (Access = private)
        
        function initTimer(app)
            if isempty(app.updateTimer)  || ~isvalid(app.updateTimer)
                app.updateTimer = timer();
                app.updateTimer.Period = 0.4;
                app.updateTimer.ExecutionMode = 'fixedRate';
                app.updateTimer.BusyMode = 'drop';
                app.updateTimer.TimerFcn = @app.updateDisplaysCallback;
            else
               stop(app.updateTimer);
            end
            
            start(app.updateTimer);
        end
        
        function enableTestRadio(app)
            app.retryCount = 0; 
            app.TestRadioCheckBox.Value = true;                
            app.RadioConfigurationUIFigure.WindowStyle='alwaysontop';
               initTimer(app)
                
        end
        
        function disableTestRadio(app)
           app.TestRadioCheckBox.Value = false;
           stop(app.updateTimer);
            app.RadioConfigurationUIFigure.WindowStyle='normal';
            
            app.APRSSIEditField.Enable = 'off';
            app.APLQIEditField.Enable = 'off';
            app.PMRSSIEditField.Enable = 'off';
            app.PMLQIEditField.Enable = 'off';
            app.Lamp.Color = [.7 .7 .7];
            app.PMSerialEditField.Enable = 'off';
        end
        
        function settings = refreshAP(app)
            %read back AP settings
            settings = app.nnp.getRadioSettings();
            if isempty(settings)
                warndlg('failed to refresh values for AP')
                return
            end
            app.APAddressEditField.Value = settings.addrAP;
            app.PMAddressEditField.Value = settings.addrPM;
            app.ChannelEditField.Value   = settings.chan;
            app.APTXPowerEditField.Value = settings.txPower;
            app.APRadioTimeoutEditField.Value = settings.rxTimeout;
            app.APRadioRetriesEditField.Value = settings.retries;
            app.APUSBTimeoutEditField.Value   = app.nnp.timeout;
            
            if settings.worInt > 0
                app.WOREditField.Enable = 'on';
                app.WOREditField.Value = settings.worInt;
                app.WakeOnRadioCheckBox.Value = 1;
            else
                app.WOREditField.Enable = 'off';
                app.WakeOnRadioCheckBox.Value = 0;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin<2
                NNP=[];
            else
                NNP=varargin{1};
            end
            if isempty(NNP)
                app.nnp = NNPAPI();
                if isempty(app.nnp.port)
                    uialert(app.RadioConfigurationUIFigure, 'No port chosen for NNP Access Point')
                    delete(app.nnp)
                    return
                end
            else
                app.nnp = NNP;
            end
            if nargin>2
                app.callingFigure=varargin{2};
            end

            app.RadioConfigurationUIFigure.WindowStyle='alwaysontop';
            settings = app.refreshAP();
            if isempty(settings)
                uialert(app.RadioConfigurationUIFigure, 'could not read back AccessPoint Settings, Radio Configuration will close',...
                    'Radio Configuration','Icon', 'error'); 
                return;
            else
                app.savedSettings = settings;
                app.savedSettings.usbTimeout = app.nnp.timeout;
            end
            
            disableDefaultInteractivity(app.RSSIAxes);
            disableDefaultInteractivity(app.LQIAxes);
            app.nSamp = 100;
            app.plotbuf = nan(app.nSamp,4); 
            plot(app.RSSIAxes, 1:app.nSamp, app.plotbuf(:,1:2));
            plot(app.LQIAxes, 1:app.nSamp, app.plotbuf(:,3:4));
            app.RSSIAxes.XLim = [0 100];
            app.LQIAxes.XLim = [0 100];
            app.RSSIAxes.YLim = [-100 -20];
            app.LQIAxes.YLim = [40 60];
            app.RSSIAxes.Children(1).Color = [1 0 0]; %AP=red
            app.RSSIAxes.Children(2).Color = [0 0 1]; %PM=blue
            app.LQIAxes.Children(1).Color = [1 0 0]; %AP=red
            app.LQIAxes.Children(2).Color = [0 0 1]; %PM=blue
            

            initTimer(app);
        end

        % Value changed function: APAddressEditField
        function APAddressEditFieldValueChanged(app, event)
            if ~app.ModifyPMCheckBox.Value 
               en = app.TestRadioCheckBox.Value;
                app.disableTestRadio();

                % prevent changes before completing current change
                app.APAddressEditField.Enable=false;
                app.PMAddressEditField.Enable=false;
                app.ChannelEditField.Enable=false;
                retry=true;
                while retry
                    if ~app.nnp.setRadio('addrAP', app.APAddressEditField.Value)
                        fig=app.RadioConfigurationUIFigure;
                        msg='Error writing AP address.  Retry?';
                        title= 'Retry Settings Update';
                        selection = uiconfirm(fig, msg,title,...
                            'Options',{'Retry','Cancel'},...
                            'DefaultOption',1,'CancelOption',2);
                        switch selection
                            case 'Retry'
                                % try again
                            case 'Cancel'
                                app.APAddressEditField.BackgroundColor = [1 0 0];
                                return
                        end
                    else
                        retry=false;
                        app.APAddressEditField.BackgroundColor = [1 1 1];
                    end
                end
                if en
                    app.enableTestRadio();
                end
            else %PM+AP Changes made when apply button pushed
                app.APAddressEditField.BackgroundColor = [1 1 0];
                app.disableTestRadio();
            end
            % allow changes again
            app.APAddressEditField.Enable=true;
            app.PMAddressEditField.Enable=true;
            app.ChannelEditField.Enable=true;
        end

        % Value changed function: PMAddressEditField
        function PMAddressEditFieldValueChanged(app, event)
            if ~app.ModifyPMCheckBox.Value 
               en = app.TestRadioCheckBox.Value;
               app.disableTestRadio(); 

                % prevent changes before completing current change
                app.APAddressEditField.Enable=false;
                app.PMAddressEditField.Enable=false;
                app.ChannelEditField.Enable=false;
                retry=true;
                while retry
                    if~app.nnp.setRadio('addrPM', app.PMAddressEditField.Value)
                        fig=app.RadioConfigurationUIFigure;
                        msg='Error writing PM address.  Retry?';
                        title= 'Retry Settings Update';
                        selection = uiconfirm(fig, msg,title,...
                            'Options',{'Retry','Cancel'},...
                            'DefaultOption',1,'CancelOption',2);
                        switch selection
                            case 'Retry'
                                % try again
                            case 'Cancel'
                                app.PMAddressEditField.BackgroundColor = [1 0 0];
                                return
                        end
                    else
                        retry=false;
                        app.PMAddressEditField.BackgroundColor = [1 1 1];
                    end
                end
               if en
                   app.enableTestRadio(); 
               end
            else %PM+AP Changes made when apply button pushed
                app.PMAddressEditField.BackgroundColor = [1 1 0];
            end
             % allow changes again
            app.APAddressEditField.Enable=true;
            app.PMAddressEditField.Enable=true;
            app.ChannelEditField.Enable=true;
        end

        % Value changed function: ChannelEditField
        function ChannelEditFieldValueChanged(app, event)
            if ~app.ModifyPMCheckBox.Value 
               en = app.TestRadioCheckBox.Value;
               app.disableTestRadio(); 

               app.TestRadioCheckBox.Value = false;
               app.TestRadioCheckBoxValueChanged();  

                % prevent changes before completing current change
                app.APAddressEditField.Enable=false;
                app.PMAddressEditField.Enable=false;
                app.ChannelEditField.Enable=false;
                retry=true;
                while retry
                    if ~app.nnp.setRadio('chan', app.ChannelEditField.Value)
                        fig=app.RadioConfigurationUIFigure;
                        msg='Could not set channel on Access Point.  Retry?';
                        title= 'Retry Settings Update';
                        selection = uiconfirm(fig, msg,title,...
                            'Options',{'Retry','Cancel'},...
                            'DefaultOption',1,'CancelOption',2);
                        switch selection
                            case 'Retry'
                                % try again
                            case 'Cancel'
                                retry=false;
                                app.ChannelEditField.BackgroundColor = [1 0 0];
                                return
                        end
                    else
                        retry=false;
                        app.ChannelEditField.BackgroundColor = [1 1 1];
                    end
                end
               if en
                   app.enableTestRadio();
               end
            else %PM+AP Changes made when apply button pushed
                app.ChannelEditField.BackgroundColor = [1 1 0];
            end
             % allow changes again
            app.APAddressEditField.Enable=true;
            app.PMAddressEditField.Enable=true;
            app.ChannelEditField.Enable=true;
        end

        % Value changed function: WOREditField
        function WOREditFieldValueChanged(app, event)
            
            if ~app.ModifyPMCheckBox.Value 
                en = app.TestRadioCheckBox.Value;
                app.disableTestRadio();
                if ~app.nnp.setRadio('worInt', app.WOREditField.Value)
                    uialert(app.RadioConfigurationUIFigure, 'Could not set wake interval on AccessPoint', 'AP Configuration','Icon', 'error');
                    app.WOREditField.BackgroundColor = [1 0 0];
                else
                    app.WOREditField.BackgroundColor = [1 1 1];
                end
               if en
                   app.enableTestRadio();
               end
            else 
                app.WOREditField.BackgroundColor = [1 1 0];
                app.WakeOnRadioCheckBoxValueChanged();
            end
            app.APUSBTimeoutEditFieldValueChanged(); %Check USB timeout is still set appropriately
        end

        % Value changed function: PMTXPowerEditField
        function PMTXPowerEditFieldValueChanged(app, event)
            app.disableTestRadio();
            value = app.PMTXPowerEditField.Value;
            if value < 20
                uialert(app.RadioConfigurationUIFigure, 'PM TX Power value < 20 may make reception of PM messages very difficult!', 'AP Configuration', 'Icon', 'warning');
            end
            % change will be applied after hitting apply button
            app.PMTXPowerEditField.BackgroundColor = [1 1 0];           
        end

        % Value changed function: APTXPowerEditField
        function APTXPowerEditFieldValueChanged(app, event)
            en = app.TestRadioCheckBox.Value;
            app.disableTestRadio(); 
            if ~app.nnp.setRadio('txPower', app.APTXPowerEditField.Value)
                  uialert(app.RadioConfigurationUIFigure, 'Could not set Transmit Power on AccessPoint', 'AP Configuration', 'Icon','error');
            else
            if en
                app.enableTestRadio();  
            end
            end
        end

        % Value changed function: APRadioTimeoutEditField
        function APRadioTimeoutEditFieldValueChanged(app, event)
            en = app.TestRadioCheckBox.Value;
            app.disableTestRadio()
            if ~app.nnp.setRadio('rxTimeout', app.APRadioTimeoutEditField.Value)
                  uialert(app.RadioConfigurationUIFigure, 'Could not set Radio Timeout on AccessPoint', 'AP Configuration', 'Icon','error');
            else
                app.APUSBTimeoutEditFieldValueChanged(); %Check USB timeout is still set appropriately
            end
            if en
                app.enableTestRadio();
            end
        end

        % Value changed function: APUSBTimeoutEditField
        function APUSBTimeoutEditFieldValueChanged(app, event)
            app.nnp.timeout = app.APUSBTimeoutEditField.Value;
            
        end

        % Button pushed function: SaveAPsettingsButton
        function SaveAPsettingsButtonPushed(app, event)
            en = app.TestRadioCheckBox.Value;
            app.disableTestRadio();
            settingsIn = app.nnp.getRadioSettings(); % get the current, temporary settings
            if isempty(settingsIn)
                uialert(app.RadioConfigurationUIFigure, 'Could not read radio settings', 'AP Configuration', 'Icon','error');
                
            else
                settings = app.nnp.setRadioSettings(settingsIn, true);  % save the temporary settings to saved
                if ~isequal(settings, settingsIn)   % if they did not save correctly...
                    uialert(app.RadioConfigurationUIFigure, 'Settings were not saved as expected', 'AP Configuration', 'Icon','error');
                    app.APAddressEditField.Value = settings.addrAP;
                    app.PMAddressEditField.Value = settings.addrPM;
                    app.ChannelEditField.Value   = settings.chan;
                    app.APTXPowerEditField.Value = settings.txPower;
                    app.WOREditField.Value       = settings.worInt;
                    app.APRadioTimeoutEditField.Value = settings.rxTimeout;
                    app.APRadioRetriesEditField.Value = settings.retries;
                    app.APUSBTimeoutEditField.Value   = app.nnp.timeout;
                    
                    if settings.worInt > 0
                        app.WakeOnRadioCheckbox.Value = 1;
                    else
                        app.WakeOnRadioCheckbox.Value = 0;
                    end
                else
                    uialert(app.RadioConfigurationUIFigure, 'Settings successfully saved.', 'AP Configuration', 'Icon','info');
                    app.savedSettings = settings;
                    app.savedSettings.usbTimeout = app.nnp.timeout;
                end
            end
            if en
                app.enableTestRadio();
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            radioConfig =   [app.PMAddressEditField.Value,...
                             app.APAddressEditField.Value,...
                             app.ChannelEditField.Value];
            resp = app.nnp.write(7, '2600', 1, uint8(radioConfig), 3, 'uint8');
            if resp ~= 0
                uialert(app.RadioConfigurationUIFigure, 'Could not write settings to PM.  Make sure AP settings are correct to communicate with PM',...
                            'AP + PM Configuration', 'Icon','error');
            else
                resp = app.nnp.read(7, '2600', 1, 3, 'uint8');
                if length(resp)==3 && all(resp == radioConfig)
                    resp = app.nnp.nmt(7, '92');  %NMT_RadioAddress
                    if resp ~= hex2dec('92')
                        uialert(app.RadioConfigurationUIFigure, 'Could not apply radio settings in PM', 'AP + PM Configuration','Icon', 'error');
                    else
                        %succeded in changing PM.  Now change AP to match
                        app.nnp.setRadio('addrPM', app.PMAddressEditField.Value,...
                                         'addrAP', app.APAddressEditField.Value,...
                                         'chan',   app.ChannelEditField.Value);
                    end
                else
                     uialert(app.RadioConfigurationUIFigure, 'Settings read back from PM do not match written settings.',...
                                'AP + PM Configuration', 'Icon','error');
                end
            end
           % app.enableTestRadio();
           
        end

        % Value changed function: WakeOnRadioCheckBox
        function WakeOnRadioCheckBoxValueChanged(app, event)
            en = app.TestRadioCheckBox.Value;
            app.disableTestRadio();
            if ~app.ModifyPMCheckBox.Value 
                
                if app.WakeOnRadioCheckBox.Value 
                    app.WOREditField.Enable = 'on';
                    if ~app.nnp.setRadio('worInt', app.WOREditField.Value)
                          uialert(app.RadioConfigurationUIFigure, 'Could not set Wake Interval on AccessPoint', 'AP Configuration','Icon', 'error');
                    end
                else
                    app.WOREditField.Enable = 'off';
                    if ~app.nnp.setRadio('worInt', 0)
                          uialert(app.RadioConfigurationUIFigure, 'Could not set Wake Interval on AccessPoint', 'AP Configuration','Icon', 'error');
                    end
                end 
            else
                if app.WakeOnRadioCheckBox.Value 
                    app.WOREditField.Enable = 'on';
                    if ~app.nnp.worOn(app.WOREditField.Value)
                        uialert(app.RadioConfigurationUIFigure, 'Could not turn on WOR on PM', 'AP+PM Configuration','Icon', 'error');
                    end
                else
                    app.WOREditField.Enable = 'off';
                    if ~app.nnp.worOff()
                        uialert(app.RadioConfigurationUIFigure, 'Could not turn off WOR on PM', 'AP+PM Configuration','Icon', 'error');
                    end
                end
            end
          if en
              app.enableTestRadio();
          end
        end

        % Close request function: RadioConfigurationUIFigure
        function RadioConfigurationUIFigureCloseRequest(app, event)
           

            try
            settings = app.nnp.getRadioSettings();
            if isempty(settings)
                disp('could not read AP settings to check if they have changed')
            elseif settings.addrAP    ~= app.savedSettings.addrAP || ...
               settings.addrPM    ~= app.savedSettings.addrPM || ...
               settings.chan      ~= app.savedSettings.chan ||...
               settings.txPower   ~= app.savedSettings.txPower||...
               settings.worInt    ~= app.savedSettings.worInt||...
               settings.rxTimeout ~= app.savedSettings.rxTimeout||...
               settings.retries   ~= app.savedSettings.retries
           
               resp = uiconfirm(app.RadioConfigurationUIFigure, 'Some AccessPoint radio settings have been changed. Do you want to save the new settings to AP Flash, so they will be preserved after power cycling the AP?',...
                   'AP Configuration', 'Options', {'Yes', 'No, exit without saving'});
               if isequal(resp, 'Yes')
                   app.SaveAPsettingsButtonPushed(event); %trigger save callback
               end
            end
            catch
                disp('May not have saved AP settings')
            end

            try
                app.disableTestRadio();
            catch
                disp('trouble disabling test radio timer')
            end
            if ~isempty(app.updateTimer) &&isvalid(app.updateTimer)
                stop(app.updateTimer);
                delete(app.updateTimer);
            end

            if ishandle(app.callingFigure) % check that is is valid
                figure(app.callingFigure); % bring to front
            end
            delete(app)
            
        end

        % Value changed function: APRadioRetriesEditField
        function APRadioRetriesEditFieldValueChanged(app, event)
            en = app.TestRadioCheckBox.Value;
            app.disableTestRadio();
            if ~app.nnp.setRadio('retries', app.APRadioRetriesEditField.Value)
                  uialert(app.RadioConfigurationUIFigure, 'Could not set Radio Retries on AccessPoint', 'AP Configuration','Icon', 'error');
            end

            app.APUSBTimeoutEditFieldValueChanged(); %Check USB timeout is still set appropriately
            if en
               app.enableTestRadio();
           end
        end

        % Value changed function: ModifyPMCheckBox
        function ModifyPMCheckBoxValueChanged(app, event)
            if app.ModifyPMCheckBox.Value 
                app.ApplyButton.Enable = 'on';
                app.disableTestRadio();  %user will have to recheck test button manually
            else
                app.ApplyButton.Enable = 'off';
                
            end
        end

        % Menu selected function: HelpMenu
        function help(app, event)
            uialert(app.RadioConfigurationUIFigure, {'If you do not know PM local/remote address or channel, use the PM bootloader to read (and/or set) them';...
                    '';...
                    '----Interpretting RSSI/LQI----';...
                    '* Low RSSI and high LQI:';...
                    '    A weak signal in the presence of noise';...
                    '* Low RSSI and low LQI:';...
                    '    A weak signal in absence of noise';...
                    '* High RSSI and high LQI:';... 
                    '    Strong noise (usually coming from an interferer)';...
                    '* Very high RSSI and high LQI:';... 
                    '    A very strong signal that causes the receiver';...
                    '    to saturate.  ove AP farther from PM';... 
                    '* High RSSI and low LQI:';... 
                    '   A strong signal without much noise ';... 
                    '';...
                    'Common Settings are only applied to the PM if "Modify PM" is checked and the AP is currently configured to communicate with the PM. In this case, boxes wit unapplied changes are highlighted in yellow, and AutoUpdates will be disabled. The changes will be applied to both the AP and PM upon hitting the "Apply" button. Which will also reenable the AutoUpdates.  If "Modify PM" is not checked, changes are applied on the AP immmediately.' },...
                    'Radio Configuration Help');
        end

        % Value changed function: TestRadioCheckBox
        function TestRadioCheckBoxValueChanged(app, event)

            if app.TestRadioCheckBox.Value
                app.enableTestRadio();
            else
                app.disableTestRadio();
            end
        end

        % Button pushed function: ApplyButton_2
        function ApplyButton_2Pushed(app, event)
            resp = app.nnp.nmt(7,153, app.PMTXPowerEditField.Value, 254); %0x99 Change TX Power, Param1=254 to save
            if ~isequal(resp, 153)
                   uialert(app.RadioConfigurationUIFigure, str, 'Could not confirm PM TX Power changed', 'Icon','error') 
            end
            %app.enableTestRadio();
        end

        % Menu selected function: RefreshMenu
        function RefreshMenuSelected(app, event)
            app.refreshAP();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RadioConfigurationUIFigure = uifigure('Visible', 'off');
            app.RadioConfigurationUIFigure.Name = 'Radio Configuration';
            app.RadioConfigurationUIFigure.CloseRequestFcn = createCallbackFcn(app, @RadioConfigurationUIFigureCloseRequest, true);
            app.HelpMenu = uimenu(app.RadioConfigurationUIFigure);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @help, true);
            app.HelpMenu.Text = 'Help';
            app.RefreshMenu = uimenu(app.RadioConfigurationUIFigure);
            app.RefreshMenu.MenuSelectedFcn = createCallbackFcn(app, @RefreshMenuSelected, true);
            app.RefreshMenu.Text = 'Refresh';
            app.RSSIAxes = uiaxes(app.RadioConfigurationUIFigure);
            ylabel(app.RSSIAxes, 'RSSI (dBm)')
            app.RSSIAxes.XTick = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.RSSIAxes.XTickLabel = '';
            app.RSSIAxes.YTick = [-100 -80 -60 -40 -20 0];
            app.RSSIAxes.YTickLabel = {'-100'; '-80'; '-60'; '-40'; '-20'; '0'};
            app.LQIAxes = uiaxes(app.RadioConfigurationUIFigure);
            ylabel(app.LQIAxes, 'LQI')
            app.LQIAxes.XTick = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.LQIAxes.XTickLabel = '';
            app.LQIAxes.YTick = [40 45 50 55 60];
            app.LQIAxes.YTickLabel = {'40'; '45'; '50'; '55'; '60'};
            app.CommonSettingsPanel = uipanel(app.RadioConfigurationUIFigure);
            app.CommonSettingsPanel.Title = 'Common Settings';
            app.APAddressEditFieldLabel = uilabel(app.CommonSettingsPanel);
            app.APAddressEditFieldLabel.Text = 'AP Address';
            app.APAddressEditField = uieditfield(app.CommonSettingsPanel, 'numeric');
            app.APAddressEditField.Limits = [1 254];
            app.APAddressEditField.ValueChangedFcn = createCallbackFcn(app, @APAddressEditFieldValueChanged, true);
            app.APAddressEditField.Value = 3;
            app.PMAddressEditFieldLabel = uilabel(app.CommonSettingsPanel);
            app.PMAddressEditFieldLabel.Text = 'PM Address';
            app.PMAddressEditField = uieditfield(app.CommonSettingsPanel, 'numeric');
            app.PMAddressEditField.Limits = [1 254];
            app.PMAddressEditField.ValueChangedFcn = createCallbackFcn(app, @PMAddressEditFieldValueChanged, true);
            app.PMAddressEditField.Value = 4;
            app.ChannelEditFieldLabel = uilabel(app.CommonSettingsPanel);
            app.ChannelEditFieldLabel.Text = 'Channel';
            app.ChannelEditField = uieditfield(app.CommonSettingsPanel, 'numeric');
            app.ChannelEditField.Limits = [0 9];
            app.ChannelEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelEditFieldValueChanged, true);
            app.ChannelEditField.Value = 5;
            app.WakeintervalmsEditFieldLabel = uilabel(app.CommonSettingsPanel);
            app.WakeintervalmsEditFieldLabel.Text = 'Wake interval (ms)';
            app.WOREditField = uieditfield(app.CommonSettingsPanel, 'numeric');
            app.WOREditField.Limits = [14 254];
            app.WOREditField.ValueChangedFcn = createCallbackFcn(app, @WOREditFieldValueChanged, true);
            app.WOREditField.Value = 20;
            app.ApplyButton = uibutton(app.CommonSettingsPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Enable = 'off';
            app.ApplyButton.Text = 'Apply';
            app.WakeOnRadioCheckBox = uicheckbox(app.CommonSettingsPanel);
            app.WakeOnRadioCheckBox.ValueChangedFcn = createCallbackFcn(app, @WakeOnRadioCheckBoxValueChanged, true);
            app.WakeOnRadioCheckBox.Text = 'Wake On Radio';
            app.WakeOnRadioCheckBox.Value = true;
            app.ModifyPMCheckBox = uicheckbox(app.CommonSettingsPanel);
            app.ModifyPMCheckBox.ValueChangedFcn = createCallbackFcn(app, @ModifyPMCheckBoxValueChanged, true);
            app.ModifyPMCheckBox.Text = 'Modify PM';
            app.IfcheckedchangeswillbeLabel = uilabel(app.CommonSettingsPanel);
            app.IfcheckedchangeswillbeLabel.Text = {'If checked, changes will only be'; 'applied to both AP and PM '; 'after hitting Apply. PM settings '; 'will save automatically. '; ''; 'If unchecked, changes will  '; 'be applied to AP immediately.'; ''; 'Settings on AP are only saved'; 'when hitting "Save AP Settings"   '; ''};
            app.PowerModuleSettingsPanel = uipanel(app.RadioConfigurationUIFigure);
            app.PowerModuleSettingsPanel.Title = 'Power Module Settings';
            app.PMTXPowerEditFieldLabel = uilabel(app.PowerModuleSettingsPanel);
            app.PMTXPowerEditFieldLabel.Text = 'PM TX Power';
            app.PMTXPowerEditField = uieditfield(app.PowerModuleSettingsPanel, 'numeric');
            app.PMTXPowerEditField.Limits = [0 46];
            app.PMTXPowerEditField.ValueChangedFcn = createCallbackFcn(app, @PMTXPowerEditFieldValueChanged, true);
            app.PMTXPowerEditField.Value = 20;
            app.ApplyButton_2 = uibutton(app.PowerModuleSettingsPanel, 'push');
            app.ApplyButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_2Pushed, true);
            app.ApplyButton_2.Text = 'Apply';
            app.AccessPointSettingsPanel = uipanel(app.RadioConfigurationUIFigure);
            app.AccessPointSettingsPanel.Title = 'Access Point Settings';
            app.hint = uilabel(app.AccessPointSettingsPanel);
            app.hint.Text = {'0-250 (ms)'; '251= 1s '; '252= 4s'; '253=10s'; '254=40s'};
            app.APRadiotimeoutEditFieldLabel = uilabel(app.AccessPointSettingsPanel);
            app.APRadiotimeoutEditFieldLabel.Text = 'AP Radio timeout';
            app.APRadioTimeoutEditField = uieditfield(app.AccessPointSettingsPanel, 'numeric');
            app.APRadioTimeoutEditField.Limits = [0 254];
            app.APRadioTimeoutEditField.ValueChangedFcn = createCallbackFcn(app, @APRadioTimeoutEditFieldValueChanged, true);
            app.APRadioTimeoutEditField.Value = 100;
            app.APUSBtimeoutsEditFieldLabel = uilabel(app.AccessPointSettingsPanel);
            app.APUSBtimeoutsEditFieldLabel.Text = 'AP USB timeout (s)';
            app.APUSBTimeoutEditField = uieditfield(app.AccessPointSettingsPanel, 'numeric');
            app.APUSBTimeoutEditField.Limits = [0 60];
            app.APUSBTimeoutEditField.ValueChangedFcn = createCallbackFcn(app, @APUSBTimeoutEditFieldValueChanged, true);
            app.APUSBTimeoutEditField.Value = 0.5;
            app.APTXPowerEditFieldLabel = uilabel(app.AccessPointSettingsPanel);
            app.APTXPowerEditFieldLabel.Text = 'AP TX Power';
            app.APTXPowerEditField = uieditfield(app.AccessPointSettingsPanel, 'numeric');
            app.APTXPowerEditField.Limits = [0 46];
            app.APTXPowerEditField.ValueChangedFcn = createCallbackFcn(app, @APTXPowerEditFieldValueChanged, true);
            app.APTXPowerEditField.Value = 20;
            app.APRadioretriesEditFieldLabel = uilabel(app.AccessPointSettingsPanel);
            app.APRadioretriesEditFieldLabel.Text = 'AP Radio retries';
            app.APRadioRetriesEditField = uieditfield(app.AccessPointSettingsPanel, 'numeric');
            app.APRadioRetriesEditField.Limits = [0 5];
            app.APRadioRetriesEditField.ValueChangedFcn = createCallbackFcn(app, @APRadioRetriesEditFieldValueChanged, true);
            app.APRadioRetriesEditField.Value = 2;
            app.APEditFieldLabel = uilabel(app.RadioConfigurationUIFigure);
            app.APEditFieldLabel.Text = 'AP ';
            app.APRSSIEditField = uieditfield(app.RadioConfigurationUIFigure, 'numeric');
            app.APRSSIEditField.Value = Inf;
            app.PMEditFieldLabel = uilabel(app.RadioConfigurationUIFigure);
            app.PMEditFieldLabel.Text = 'PM';
            app.PMRSSIEditField = uieditfield(app.RadioConfigurationUIFigure, 'numeric');
            app.PMRSSIEditField.Value = Inf;
            app.APEditField_2Label = uilabel(app.RadioConfigurationUIFigure);
            app.APEditField_2Label.Text = 'AP ';
            app.APLQIEditField = uieditfield(app.RadioConfigurationUIFigure, 'numeric');
            app.APLQIEditField.Value = Inf;
            app.PMEditField_2Label = uilabel(app.RadioConfigurationUIFigure);
            app.PMEditField_2Label.Text = 'PM';
            app.PMLQIEditField = uieditfield(app.RadioConfigurationUIFigure, 'numeric');
            app.PMLQIEditField.Value = Inf;
            app.SaveAPsettingsButton = uibutton(app.RadioConfigurationUIFigure, 'push');
            app.SaveAPsettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAPsettingsButtonPushed, true);
            app.SaveAPsettingsButton.Text = 'Save AP settings';
            app.TestRadioCheckBox = uicheckbox(app.RadioConfigurationUIFigure);
            app.TestRadioCheckBox.ValueChangedFcn = createCallbackFcn(app, @TestRadioCheckBoxValueChanged, true);
            app.TestRadioCheckBox.Text = 'Test Radio';
            app.TestRadioCheckBox.Value = true;
            app.Lamp = uilamp(app.RadioConfigurationUIFigure);
            app.PMSerialEditFieldLabel = uilabel(app.RadioConfigurationUIFigure);
            app.PMSerialEditFieldLabel.Text = 'PM Serial#';
            app.PMSerialEditField = uieditfield(app.RadioConfigurationUIFigure, 'numeric');
            app.PMSerialEditField.Value = Inf;
            % Show the figure after all components are created
            app.RadioConfigurationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = radioconfig(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.RadioConfigurationUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.RadioConfigurationUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RadioConfigurationUIFigure)
        end
    end
end