classdef rmSetup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Menu                      matlab.ui.container.Menu
        OpenMenu                  matlab.ui.container.Menu
        SaveMenu                  matlab.ui.container.Menu
        SaveAsMenu                matlab.ui.container.Menu
        SyncPlotButton            matlab.ui.control.Button
        FilenameEditField         matlab.ui.control.EditField
        FilenameEditFieldLabel    matlab.ui.control.Label
        Panel                     matlab.ui.container.Panel
        UserIDEditField           matlab.ui.control.EditField
        WriteSyncButton           matlab.ui.control.Button
        ReadSyncButton            matlab.ui.control.Button
        SaveSyncButton            matlab.ui.control.Button
        ColEditField              matlab.ui.control.NumericEditField
        ColEditFieldLabel         matlab.ui.control.Label
        WriteSwitch               matlab.ui.control.Switch
        WriteSwitchLabel          matlab.ui.control.Label
        SavePGButton              matlab.ui.control.Button
        SaveBPButton              matlab.ui.control.Button
        SyncPeriodEditField       matlab.ui.control.NumericEditField
        SyncPeriodEditFieldLabel  matlab.ui.control.Label
        ClearMessageButton        matlab.ui.control.Button
        Messages                  matlab.ui.control.TextArea
        WritePGButton             matlab.ui.control.Button
        WriteBPButton             matlab.ui.control.Button
        NetworkOffButton          matlab.ui.control.Button
        NetworkLamp               matlab.ui.control.Lamp
        NetworkLampLabel          matlab.ui.control.Label
        StimNodesEditField        matlab.ui.control.EditField
        StimNodesEditFieldLabel   matlab.ui.control.Label
        EMGNodeEditField          matlab.ui.control.NumericEditField
        EMGNodeEditFieldLabel     matlab.ui.control.Label
        ReadBPSettingsButton      matlab.ui.control.Button
        ReadPGTimingButton        matlab.ui.control.Button
        TabGroup                  matlab.ui.container.TabGroup
        EMGTab                    matlab.ui.container.Tab
        BPdefaultButton           matlab.ui.control.Button
        BPconfigTable             matlab.ui.control.Table
        CalcSendEditField         matlab.ui.control.NumericEditField
        CalcSendEditFieldLabel    matlab.ui.control.Label
        BPscheduleTable           matlab.ui.control.Table
        SyncIntEditField          matlab.ui.control.NumericEditField
        SyncIntEditFieldLabel     matlab.ui.control.Label
        DecodeFeaturesButton      matlab.ui.control.Button
        StimTab                   matlab.ui.container.Tab
        PGsetupTable              matlab.ui.control.Table
        TimingAxes                matlab.ui.control.UIAxes
    end

    % I want to put all the data into variables first, then update the display, in order to make it easeier to load and
    % save the data to mat file later.
    
    properties (Access = public)
        rmParam=[];
        bpConfigTable=[]; %empty table
        nnp=[];
        rmfilename;
        pathname;
        Xdata=[];
        YdataBP=[]; % 1D array of BP data
        unblank=[]; % matrix of unblank regions
        YdataStim=[];  % 2D array of stim plots
        YdataCalc=[]; % 1D array of Calc data
        %
        x1=[];
        p1=[];
        p2=[];
        p3=[];
        pgnumplots=6; %default number of plots is 6
        syncintmax=1; %default max syncint
        xdatamax=60; %max value for x, based on sync period and max syncint
        callingFigure=[]; %handle to calling figure
        % Code for auto file open
         userFolders=struct;  % userfiles, code, user, config, data, log
         defaultSuffix='_RM_Parameters.mat';
         defaultName='P00_RM_Parameters.mat';
    end
    
    methods (Access = private)
        
        function writeErrCheck(app,result,message,node)
            if isempty(result)
                dmessage=strcat('failed to write ',message,' ',num2str(node));
                app.TopMessage(dmessage);
            end
        end
    end
    
    methods (Access = public)
        
        function getBPdata(app)
            rx=app.nnp.read(7,'1006',0,1,'uint32'); %Sync Period
            if ~isempty(rx)
                app.rmParam.syncPeriod=double(rx);
                app.SyncPeriodEditField.Value=double(rx);
                app.TopMessage('Read Sync Period');
            else
                app.TopMessage('Could not read Sync Period');
            end
            app.nnp.enterWaiting();   % waiting mode
            success=app.nnp.networkOn();  % network on
            if success %true if successful
                app.NetworkLamp.Color=[0 1 0];
                app.TopMessage('Reading BP parameters...');
                pause(1); % wait for network to start
                
                
                node=uint8(app.EMGNodeEditField.Value);
                
                % 2800 Sync Timing
                % 	1	Sync timing array [23]
                % 	2	Sync interval
                
                rx=app.nnp.read(node,'2800',1,1,'uint8'); %Sync Timing
                if ~isempty(rx)
                    app.rmParam.bpSyncSchedule=double(rx);
                    
                end
                
                rx=app.nnp.read(node,'2800',2,1,'uint8'); %Sync Interval
                if ~isempty(rx)
                    app.rmParam.bpSyncInt=double(rx);
                end
                
                % 3411 Ch 1 Configuration
                % 	1	Gain
                % 	2	Depth (running average)
                % 	3	Threshold ZX
                % 	4	Threshold SSC
                % 	5	MAV Gain
                % 	6	MV Gain
                % 	7	WFL Gain
                rx=app.nnp.read(node,'3411',1,7,'uint8');
                if ~isempty(rx)
                    app.rmParam.bpCh1(1:7)=double(rx); % first seven rows
                end
                % 3412 Ch 1 Features
                % 	6	MAV, WFL,Acc
                rx=app.nnp.read(node,'3412',6,1,'uint8'); % feature set 1
                if ~isempty(rx)
                    app.rmParam.bpCh1(8)=double(rx);
                end
                % 3511 Ch 2 Configuration
                % 	1	Gain
                % 	2	Depth (running average)
                % 	3	Threshold ZX
                % 	4	Threshold SSC
                % 	5	MAV Gain
                % 	6	MV Gain
                % 	7	WFL Gain
                rx=app.nnp.read(node,'3511',1,7,'uint8');
                if ~isempty(rx)
                    app.rmParam.bpCh2(1:7)=double(rx); %first seven rows
                end
                % 3512 Ch 2 Features
                % 	6	MAV, WFL,Acc
                rx=app.nnp.read(node,'3512',6,1,'uint8'); % feature set 2
                if ~isempty(rx)
                    app.rmParam.bpCh2(8)=double(rx);%
                end
                app.updatebptables();
                app.TopMessage('Finished reading BP parameters');
            else
                app.TopMessage('Not able to start network');
            end
        end
        
        function getPGdata(app)
            rx=app.nnp.read(7,'1006',0,1,'uint32'); %Sync Period
            if ~isempty(rx)
                app.rmParam.syncPeriod=double(rx);
                app.SyncPeriodEditField.Value=double(rx);
                app.TopMessage('Read Sync Period');
            else
                app.TopMessage('Could not read Sync Period');
            end
            app.nnp.enterWaiting();   % waiting mode
            success=app.nnp.networkOn();  % network on
            if success %true if successful
                app.NetworkLamp.Color=[0 1 0];
                app.TopMessage('Reading PG timing...');
                pause(1); % wait for network to start
                
                app.rmParam.pgNodeList=str2num(app.StimNodesEditField.Value); % array of PG nodes
                numnodes=size(app.rmParam.pgNodeList,2);
                for n=1:numnodes
                    node=app.rmParam.pgNodeList(n);
                    % start with existing array from table
                    % Remember this is a cell array of strings
                    pga=app.rmParam.pgTiming(:,n); % cell number array
                    %                   pga=app.PGsetupTable.Data(:,n); % cell array, 13x1, so use {} notation to update
                    pga(1)={app.rmParam.pgNodeList(node)};
                    
                    % 2800	1	UNS8	StimTiming[4]	  W	{20,21,22,23}	time at which pulses are scheduled to occur (may get bumped if setup takes longer than time allotted)
                    % 2800	2	UNS8	SyncInterval[4]   W	{1,1,1,1}	number of syncs before scheduler starts for each channel
                    rx=app.nnp.read(node,'2800',1,1,'uint8'); % Stim Timing
                    if ~isempty(rx)
                        pga(2)={double(rx)};
                    end
                    rx=app.nnp.read(node,'2800',2,1,'uint8'); %Sync Interval
                    if ~isempty(rx)
                        pga(3)={double(rx)};
                    end
                    
                    % 2800	3	UNS8	SyncPush 	  W	0	"sub-ms lining up between modules relative to SYNC, At 1 or 8 MHz, in units of 8us (max setting = 124)
                    % 2800	4	UNS8	AutoSyncTime	  W	255	tick in scheduler when autosync will be generated.  255 disables autosync
                    % 2800	5	UNS8	MaxAutoSyncCount  W	5	max consecutive auto syncs allowed (set to 255 for indefinite)
                    % 2800	6	UNS8	MinDischargeTime  W	5	minimum time required for discharge after pulse (<4 will be treated as 4)
                    
                    rx=app.nnp.read(node,'2800',3,4,'uint8'); %
                    if ~isempty(rx)
                        pga(4:7)=num2cell(double(rx));
                    end
                    
                    % 3210	3	UNS16	StimVOS   v*60	  W	2040	34v*60=2040
                    % 3210	4	UNS16	MinVOS v*60	  W	2040	set to 10 (600) to reduce power.
                    rx=app.nnp.read(node,'3210',3,2,'uint16'); %
                    if ~isempty(rx)
                        pga(8:9)=num2cell(double(rx));
                    end
                    % 3210	6	UNS8	StimVOSsteps	  W	1	increments used for raising VOS from MinVOS to StimVOS (Setting >4 may offset stim timing, takes ~0.2ms per step. )
                    % 3210	7	UNS8	MinVOSsteps	  W	50	increments used for raising VOS from off to MinVOS (Use > 20 to reduce VIN sag, when setting MinVOS=StimVOS.  Takes ~0.2ms per step.
                    % 3210	8	UNS8	SetupAnode	  W	0	Anode connection: 0 leaves anode connected to case all the time, 1 disconnects anode between groups of pulses. Connected by default on startup until first pulse.
                    % 3210	9	UNS8	Channel_IPI	  W	50	time between cathodic and anodic phase
                    rx=app.nnp.read(node,'3210',6,4,'uint8'); %
                    if ~isempty(rx)
                        pga(10:13)=num2cell(double(rx));
                    end
                    % replace the column in PG table
                    app.rmParam.pgTiming(:,n)=pga;
                end
                app.TopMessage('Finished reading PG timing...');
            else
                app.TopMessage('Not able to start network');
            end
            app.updatepgtables();
        end
        
        function defaultPGcellarray(app)
            % start with a single column then
            %Start with a cell array of numerical data, thn convert them to strings with one command
            defaultTimingData=cell(13,6); % this creates a table, from which we will select our nodes
            defaultTimingData(:,1)={1;[36, 37, 38, 39];[1,1,1,1];0;255;5;5;2040;2040;1;50;0;50};
            defaultTimingData(:,2)={2;[37, 38, 39, 40];[1,1,1,1];62;255;5;5;2040;2040;1;50;0;50};
            defaultTimingData(:,3)={3;[40, 41, 42, 43];[1,1,1,1];0;255;5;5;2040;2040;1;50;0;50};
            defaultTimingData(:,4)={4;[41, 42, 43, 44];[1,1,1,1];62;255;5;5;2040;2040;1;50;0;50};
            defaultTimingData(:,5)={5;[44, 45, 46, 47];[1,1,1,1];0;255;5;5;2040;2040;1;50;0;50};
            defaultTimingData(:,6)={6;[45, 46, 47, 48];[1,1,1,1];62;255;5;5;2040;2040;1;50;0;50};
            % determine which columns will be needed
            nodeList=app.rmParam.pgNodeList;
            numNodes=size(nodeList,2); % we want to fill the table from 1-nodeList
            
            app.rmParam.pgTiming=cell(13,numNodes); % this will be a subset of defaultTimingData
            % this method should allow the nodes to be listed in any order
            % or combination
            for i=1:numNodes
                app.rmParam.pgTiming(:,i)=defaultTimingData(:,i);
                n=nodeList(i); % get node identifier in the node list
                app.rmParam.pgTiming{1,i}=n; % fill in the correct node number
            end
           
%             app.rmParam.pgTiming(:,1)={1;[36, 37, 38, 39];[1,1,1,1];0;255;5;5;2040;2040;1;50;0;50};
%             app.rmParam.pgTiming(:,2)={2;[37, 38, 39, 40];[1,1,1,1];62;255;5;5;2040;2040;1;50;0;50};
%             app.rmParam.pgTiming(:,3)={3;[40, 41, 42, 43];[1,1,1,1];0;255;5;5;2040;2040;1;50;0;50};
%             app.rmParam.pgTiming(:,4)={4;[41, 42, 43, 44];[1,1,1,1];62;255;5;5;2040;2040;1;50;0;50};
%             app.rmParam.pgTiming(:,5)={5;[44, 45, 46, 47];[1,1,1,1];0;255;5;5;2040;2040;1;50;0;50};
%             app.rmParam.pgTiming(:,6)={6;[45, 46, 47, 48];[1,1,1,1];62;255;5;5;2040;2040;1;50;0;50};
            
            
            % 2800	1	UNS8	StimTiming[4]	  W	{20,21,22,23}	time at which pulses are scheduled to occur (may get bumped if setup takes longer than time allotted)
            % 2800	2	UNS8	SyncInterval[4]   W	{1,1,1,1}	number of syncs before scheduler starts for each channel
            
            % 2800	3	UNS8	SyncPush 	  W	0	"sub-ms lining up between modules relative to SYNC, At 1 or 8 MHz, in units of 8us (max setting = 124)
            % 2800	4	UNS8	AutoSyncTime	  W	255	tick in scheduler when autosync will be generated.  255 disables autosync
            % 2800	5	UNS8	MaxAutoSyncCount  W	5	max consecutive auto syncs allowed (set to 255 for indefinite)
            % 2800	6	UNS8	MinDischargeTime  W	5	minimum time required for discharge after pulse (<4 will be treated as 4)
            
            % 3210	3	UNS16	StimVOS   v*60	  W	2040	34v*60=2040
            % 3210	4	UNS16	MinVOS v*60	  W	2040	set to 10 (600) to reduce power.
            
            % 3210	6	UNS8	StimVOSsteps	  W	1	increments used for raising VOS from MinVOS to StimVOS (Setting >4 may offset stim timing, takes ~0.2ms per step. )
            % 3210	7	UNS8	MinVOSsteps	  W	50	increments used for raising VOS from off to MinVOS (Use > 20 to reduce VIN sag, when setting MinVOS=StimVOS.  Takes ~0.2ms per step.
            % 3210	8	UNS8	SetupAnode	  W	0	Anode connection: 0 leaves anode connected to case all the time, 1 disconnects anode between groups of pulses. Connected by default on startup until first pulse.
            % 3210	9	UNS8	Channel_IPI	  W	50	time between cathodic and anodic phase
            %
            app.PGsetupTable.Data=cellfun(@num2str,app.rmParam.pgTiming,'UniformOutput',false);
            
        end
        
        function Write2BP(app)
            app.nnp.enterWaiting();   % waiting mode
            success=app.nnp.networkOn();  % network on
            if success %true if successful
                app.NetworkLamp.Color=[0 1 0];
                app.TopMessage('Writing BP parameters...');
                pause(1); % wait for network to start
                
                % update variables to reflect changes to the displayed tables
                % write data to the BP module
                % confirm what was written
                
                node=uint8(app.EMGNodeEditField.Value);
                app.BPtables2Variables();
                % 2800 Sync Timing
                % 	1	Sync timing array [23]   app.rmParam.bpSyncSchedule
                % 	2	Sync interval
                % 3411 Ch 1 Configuration    app.rmParam.bpCh1
                % 	1	Gain
                % 	2	Depth (running average)
                % 	3	Threshold ZX
                % 	4	Threshold SSC
                % 	5	MAV Gain
                % 	6	MV Gain
                % 	7	WFL Gain
                % 3412 Ch 1 Features
                % 	6	MAV, WFL,Acc
                % 3511 Ch 2 Configuration    app.rmParam.bpCh1
                % 	1	Gain
                % 	2	Depth (running average)
                % 	3	Threshold ZX
                % 	4	Threshold SSC
                % 	5	MAV Gain
                % 	6	MV Gain
                % 	7	WFL Gain
                % 3512 Ch 2 Features
                % 	6	MAV, WFL,Acc
                % dataOut = write(NNP, node, indexOD, subIndexOD, writeData, writeType, numSubIndices)
                result = app.nnp.write(node, '2800', 1, uint8(app.rmParam.bpSyncSchedule), 'uint8');
                if ~isequal(result, 0)
                    app.TopMessage('Error writing bp schedule');
                end
                
                result = app.nnp.write(node, '2800', 2, uint8(app.rmParam.bpSyncInt), 'uint8');
                if ~isequal(result, 0)
                    app.TopMessage('Error writing bp sync int')
                end
                
                for i=1:7
                    result = app.nnp.write(node, '3411', i, uint8(app.rmParam.bpCh1(i)), 'uint8');  % block write not supported for RM
                    
                    if ~isequal(result, 0)
                        app.TopMessage('Error writing bp cfg 1')
                    end
                end
                result = app.nnp.write(node, '3412', 6, uint8(app.rmParam.bpCh1(8)), 'uint8'); % Features 1
                if ~isequal(result, 0)
                    app.TopMessage('Error writing bp features 1')
                end
                for i=1:7
                    result = app.nnp.write(node, '3511', i, uint8(app.rmParam.bpCh2(i)), 'uint8');
                    if ~isequal(result, 0)
                        app.TopMessage('Error writing bp cfg 2')
                    end
                end
                
                result = app.nnp.write(node, '3512', 6, uint8(app.rmParam.bpCh2(8)), 'uint8'); % Features 2
                if ~isequal(result, 0)
                    app.TopMessage('Error writing bp features 2')
                end
                app.TopMessage('Finished writing BP Parameters...');
            end
        end
        
        function Write2PG(app)
            % RM do not support block writes, so need to update each subindex separately
            % should make a table of the index, subindex and data
            app.nnp.enterWaiting();   % waiting mode
            success=app.nnp.networkOn();  % network on
            if success %true if successful
                app.NetworkLamp.Color=[0 1 0];
                app.TopMessage('Writing PG timing...');
                pause(1); % wait for network to start
                % network on
                % get the data, then update the UI tables
                app.rmParam.pgNodeList=str2num(app.StimNodesEditField.Value); % array of PG nodes
                numnodes=size(app.rmParam.pgNodeList,2);
                app.PGtables2Variables();
                % a quick way to be able to write a single column but
                % maintain the entire node table.  I probably should just
                % give and error and not write anything of column out of
                % range.
                if app.WriteSwitch.Value=='One'
                    maxCol=numnodes;
                    numnodes=1;
                end
                for n=1:numnodes
                    if app.WriteSwitch.Value=='One'
                        a=app.ColEditField.Value;
                        if a>=1&&a<=maxCol % check for range
                            nx=a; % should check here to make sure in valid range  
                        else
                            %nx=1; %set column 1 if out of range -  could just put return
                            app.TopMessage('Column outside of range');
                            return
                        end
                    else
                        nx=n;
                    end
                    node=app.rmParam.pgNodeList(nx);
                    pga=app.rmParam.pgTiming(:,nx); % cell number array
                    
                    % 2800	1	UNS8	StimTiming[4]	  W	{20,21,22,23}	time at which pulses are scheduled to occur (may get bumped if setup takes longer than time allotted)
                    % 2800	2	UNS8	SyncInterval[4]   W	{1,1,1,1}	number of syncs before scheduler starts for each channel
                    % 2800	3	UNS8	SyncPush 	  W	0	"sub-ms lining up between modules relative to SYNC, At 1 or 8 MHz, in units of 8us (max setting = 124)
                    % 2800	4	UNS8	AutoSyncTime	  W	255	tick in scheduler when autosync will be generated.  255 disables autosync
                    % 2800	5	UNS8	MaxAutoSyncCount  W	5	max consecutive auto syncs allowed (set to 255 for indefinite)
                    % 2800	6	UNS8	MinDischargeTime  W	5	minimum time required for discharge af
                    
                    % 3210	3	UNS16	StimVOS   v*60	  W	2040	34v*60=2040
                    % 3210	4	UNS16	MinVOS v*60	  W	2040	set to 10 (600) to reduce power.
                    
                    % 3210	6	UNS8	StimVOSsteps	  W	1	increments used for raising VOS from MinVOS to StimVOS (Setting >4 may offset stim timing, takes ~0.2ms per step. )
                    % 3210	7	UNS8	MinVOSsteps	  W	50	increments used for raising VOS from off to MinVOS (Use > 20 to reduce VIN sag, when setting MinVOS=StimVOS.  Takes ~0.2ms per step.
                    % 3210	8	UNS8	SetupAnode	  W	0	Anode connection: 0 leaves anode connected to case all the time, 1 disconnects anode between groups of pulses. Connected by default on startup until first pulse.
                    % 3210	9	UNS8	Channel_IPI	  W	50	time between cathodic and anodic phase
                    % to do:  make an table of index, subindex, datatype, message then put this in a loop
                    % probably take as many lines
                    result = app.nnp.write(node, '2800', 1, uint8(pga{2}), 'uint8'); % use {}
                    app.writeErrCheck(result,'stim timing',node);
                    result = app.nnp.write(node, '2800', 2, uint8(pga{3}), 'uint8'); % use {}
                    app.writeErrCheck(result,'stim timing',node);
                    result = app.nnp.write(node, '2800', 3, uint8(pga{4}), 'uint8'); % use {}
                    app.writeErrCheck(result,'stim timing',node);
                    result = app.nnp.write(node, '2800', 4, uint8(pga{5}), 'uint8'); % use {}
                    app.writeErrCheck(result,'stim timing',node);
                    result = app.nnp.write(node, '2800', 5, uint8(pga{6}), 'uint8'); % use {}
                    app.writeErrCheck(result,'stim timing',node);
                    result = app.nnp.write(node, '2800', 6, uint8(pga{7}), 'uint8'); % use {}
                    app.writeErrCheck(result,'stim timing',node);
                    result = app.nnp.write(node, '3210', 3, uint16(pga{8}), 'uint16'); % use {}
                    app.writeErrCheck(result,'stim timing',node);
                    result = app.nnp.write(node, '3210', 4, uint16(pga{9}), 'uint16'); % use {}
                    app.writeErrCheck(result,'stim timing',node);
                    result = app.nnp.write(node, '3210', 6, uint8(pga{10}), 'uint8'); % use {}
                    app.writeErrCheck(result,'stim timing',node);
                    result = app.nnp.write(node, '3210', 7, uint8(pga{11}), 'uint8'); % use {}
                    app.writeErrCheck(result,'stim timing',node);
                    result = app.nnp.write(node, '3210', 8, uint8(pga{12}), 'uint8'); % use {}
                    app.writeErrCheck(result,'stim timing',node);
                    result = app.nnp.write(node, '3210', 9, uint8(pga{13}), 'uint8'); % use {}
                    app.writeErrCheck(result,'stim timing',node);
                    % add NMT_Update_Scheduler 0xC2 to update timing for
                    response=app.nnp.nmt(node, 'C2');
                    if response~=hex2dec('C2')                        
                        app.TopMessage(['Error NMT_Update_Sched node ',num2str(node)]);
                    end
                end
                app.TopMessage('Finished writing PG timing');
            end
        end
        
        function BPtables2Variables(app)
            % read data from tables and store in the variables, so they are now all doubles
            app.rmParam.bpSyncSchedule=[app.BPscheduleTable.Data(:,1)',app.BPscheduleTable.Data(:,2)',app.CalcSendEditField.Value];
            app.rmParam.bpCh1=app.BPconfigTable.Data{:,1}; %use braced because dat is table array
            app.rmParam.bpCh2=app.BPconfigTable.Data{:,2}; %use braced because dat is table array
            app.rmParam.bpSyncInt=app.SyncIntEditField.Value;
            app.rmParam.bpNode=app.EMGNodeEditField.Value; %
        end
        
        function ClearMessages(app)
            app.Messages.Value=''; % clear messages
        end
        
        function TopMessage(app,message)
            app.Messages.Value=vertcat(message, string(app.Messages.Value)); % put new message on top
        end
        
        function PGtables2Variables(app)
            % need to fill in
            app.rmParam.pgNodeList=str2num(app.StimNodesEditField.Value);
            app.rmParam.pgTiming=cellfun(@str2num,app.PGsetupTable.Data,'UniformOutput',false); %convert back to numbers
        end
        
        
        function updatepgtables(app)
            app.PGsetupTable.Data=cellfun(@num2str,app.rmParam.pgTiming,'UniformOutput',false);
        end
        
        function updatebptables(app)
            app.BPscheduleTable.Data=[app.rmParam.bpSyncSchedule(1:11);app.rmParam.bpSyncSchedule(12:22)]';
            app.CalcSendEditField.Value=app.rmParam.bpSyncSchedule(23);
            app.SyncIntEditField.Value=app.rmParam.bpSyncInt;
            app.bpConfigTable=table(app.rmParam.bpCh1,app.rmParam.bpCh2,'VariableNames',{'Ch1','Ch2'});
            app.BPconfigTable.Data=app.bpConfigTable;
        end
        
        function quit = checkDoSave(app)
            % if the structure has changed, then need to check whether the user wants to save the current file before
            % opening another.
            quit = false;
            if ~isempty(app.rmfilename) %file already exists
                
                %check if any changes have been made
                savedValues = load(app.rmfilename);
                %if exist(savedValues.rmParam)
                if ~isequal(savedValues.rmParam, app.rmParam)
                    
                    userResp = questdlg(sprintf(['Changes detected in RM parameter file.\n Do you want to save changes to:\n',...
                        '"%s"?\n\nHit Cancel and choose File/Save As if you want to save into a new file'], app.rmfilename),...
                        'RM Parameter File');
                    if isequal(userResp, 'Yes')
                        app.SaveMenuSelected();
                    elseif isequal(userResp, 'No')
                        %nothing to do here
                    else %Cancel
                        quit = true;
                    end
                end
                %end
            elseif ~isempty(app.rmParam) %no file exists yet, but something has been done
                userResp = questdlg('Save RM parameter File?','RM Parameter File');
                if isequal(userResp, 'Yes')
                    app.SaveAsMenuSelected();
                elseif isequal(userResp, 'No')
                    %nothing to do here
                else %Cancel
                    quit = true;
                end
            end
        end
        
        function plotSyncTiming(app)
            hold(app.TimingAxes, 'off'); % first plot I need to refresh so turn hold off
            app.p1=plot(app.TimingAxes,app.Xdata,app.YdataStim,'.','MarkerSize',20);
            hold(app.TimingAxes, 'on');
            
            % ub means unblank
            ubcolor = [0 1 0];
            uby = 7; %max node
            n= size(app.unblank,1); %number of recording windows
            for i = 1:n
                ubp = patch(app.TimingAxes, [app.unblank(i,1) app.unblank(i,1) app.unblank(i,2) app.unblank(i,2)], [0 uby uby 0], ubcolor);
                ubp.LineStyle = 'none';
                ubp.FaceAlpha = 0.5;
            end                    
            app.p3=stem(app.TimingAxes,app.Xdata,app.YdataCalc,'d','filled','MarkerFaceColor',[0,0,0],'MarkerEdgeColor',[0,0,0],'MarkerSize',8);
            %           app.TimingAxes.XLim=[0,60]; % change XLim to sync period*syncInt
            app.TimingAxes.XLim=[0,app.xdatamax];            
            app.TimingAxes.YLim=[0.4,7.2];
            app.TimingAxes.XTickMode='auto';
            app.TimingAxes.XGrid='off';
            hold(app.TimingAxes,'off');
        end
        
        function calcSyncPlot(app)
            % intervals are the same, then the length only need to be sync period * sync interval.
            % If the sync intervals are not the same, then the length can be more complicated
            % Start with rmParam and calculate the sync data for plots.
            
            app.pgnumplots=size(app.rmParam.pgNodeList,2); %number of PG plots
            simax=1; %start with 1
            simax=max(simax,app.rmParam.bpSyncInt); % first check BP
            % check through each of the sync intervals
            for i=1:app.pgnumplots
                maxpg=max(app.rmParam.pgTiming{3,i});
                simax=max(simax,maxpg); % check through each of the sync intervals
            end
            
            if simax>1
                app.xdatamax=simax*app.rmParam.syncPeriod;
            else
                app.xdatamax=app.rmParam.syncPeriod;
            end
            xRange=0.5:0.5:app.xdatamax; % xrange for the data
            app.Xdata=xRange; % xrange for data
            plotpoints=size(app.Xdata,2);
            
            % calculate the BP ydata
            % To start with, I could assume that both channels with read at the same time, but they don't need to.   I
            % really should plot two sets of BP data, one for each channel, since they are independent.  That requires
            % Ydata BP1 and Ydata BP2.   To start with I will include only the first set.
            
            app.unblank=zeros(1,2); % start, stop columns
            app.YdataCalc=zeros(1,plotpoints); % start with all zeros
            
            % The BP array is arranged with points 1-11 representing pairs of start, stop recoding and 255 is end of
            % recording.   So you can record in segments.
            bp1=app.rmParam.bpSyncSchedule(1:11); % channel 1 of schedule
            %assume tht all value up to i=1 are zeros
            region=1;
            app.unblank(region,1)=bp1(1); % first start point
            newrange=true; % Start with new range true
            finished=false;
            for i=2:11
                if ~finished
                    if bp1(i)==255 %end of schedule
                        finished=true;
                    else
                        if newrange
                            app.unblank(region,2)= bp1(i); % end of region
                            newrange=false;
                        else % starting new range
                            region=region+1; % increment region
                            app.unblank(region,1)= bp1(i); % create a new region
                            newrange=true;
                        end
                    end
                end
            end
            
            % calculuate the synccalc ydata
            app.YdataCalc(app.rmParam.bpSyncSchedule(23)*2)=6.5;
            % how many PG plots
            % for each plot, calculate the y data
            app.YdataStim=zeros(app.pgnumplots,plotpoints); %initialize array
            for i=1:app.pgnumplots
                % assume for this purpose that sp>0 means shift half ms;
                stimpts=app.rmParam.pgTiming{2,i}*2+logical(app.rmParam.pgTiming{4,i}); %shift by half ms if >0
                app.YdataStim(i,stimpts)=i;
            end
            % if any of the sync intervals is > 1, the plotting will get trickier because the timing of the other plots
            % will need to adjust accordingly.   Also the timing of when each starts is trickier.   Will start with case
            % of sync interval is 1, but keep in mind that we need to include the other cases.
        end
        
        function OpenFileByName(app,filename,filepath)              
             if filename == 0
                return
            else
                app.rmfilename = fullfile(filepath, filename);
                app.FilenameEditField.Value=app.rmfilename;
            end
            
            % app.rmParam = load(app.rmfilename);
            in=load(app.rmfilename);
            if ~isfield(in, 'rmParam') % this file does not contain the right structure
                % here I could test also test the number of fields in rmParam
                msgbox('Remote Module  mat file must contain the structure variable named "rmParam". Please try again.');
            else
                % Need similar checks as below (from scriptedit)
                %             if ~isfield(app.scripts, 'active') || ~isfield(app.scripts, 'library')
                %                 msgbox('Scripts mat file must contain variables named "active" and "library"')
                %                 if ~isnumeric(app.scripts.active) || ~isequal(size(app.scripts.active), [25,1])
                %                     msgbox('Scripts mat file must contain 25x1 vector named "active" ');
                %                 end
                %             end
                %             if ~isfield(app.scripts, 'order')
                %                 app.scripts.order = [];
                %             end
                app.rmParam=in.rmParam; %copy to app
                app.EMGNodeEditField.Value=app.rmParam.bpNode;
                app.StimNodesEditField.Value=num2str(app.rmParam.pgNodeList);
                app.rmParam.syncPeriod=app.rmParam.syncPeriod;
                app.SyncPeriodEditField.Value=app.rmParam.syncPeriod;
                app.updatebptables();
                app.updatepgtables();
                app.TopMessage('Data loaded from file');
                app.SyncPlotButtonPushed()
            end
            figure(app.UIFigure); % return focus
        end
        
        function defaultBPSettings(app)
            app.rmParam.bpSyncSchedule=[15,33,255,0,0,0,0,0,0,0,0,15,33,255,0,0,0,0,0,0,0,0,32];
            app.rmParam.bpSyncInt=1;
            app.rmParam.syncPeriod=app.SyncPeriodEditField.Value; % need startup parameter to create field 
            app.rmParam.bpCh1=[2,2,0,0,2,2,25,81]'; %array 1
            app.rmParam.bpCh2=[2,2,0,0,2,2,25,81]'; %array 2
            app.rmParam.bpNode=app.EMGNodeEditField.Value; % default node is 9
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin<2
                NNP=[];
            else
                NNP=varargin{1};
            end
            if isempty(NNP)
                app.nnp = NNPAPI();
                if isempty(app.nnp.port)
                    %msgbox('No port chosen for NNP Access Point')
                    uialert(app.UIFigure,'No port chosen for NNP Access Point','No NNP AP')
                    delete(app.nnp)
                    return
                 end
            else
                app.nnp = NNP;
            end
            if nargin>2
                app.callingFigure=varargin{2};
            end
            if nargin>3  % third is userFolders
                    app.userFolders=varargin{3};  % userfiles, code, user, config, data, log
                    app.UserIDEditField.Value=app.userFolders.code;
            end
            app.defaultBPSettings();
%             app.rmParam.bpSyncSchedule=[15,33,255,0,0,0,0,0,0,0,0,15,33,255,0,0,0,0,0,0,0,0,32];
%             app.rmParam.bpSyncInt=1;
%             app.rmParam.syncPeriod=app.SyncPeriodEditField.Value; % need startup parameter to create field 
%             app.rmParam.bpCh1=[2,2,0,0,2,2,25,81]'; %array 1
%             app.rmParam.bpCh2=[2,2,0,0,2,2,25,81]'; %array 2
            app.rmParam.pgNodeList=str2num(app.StimNodesEditField.Value);
            app.rmParam.pgTiming={[]};
%             app.rmParam.bpNode=app.EMGNodeEditField.Value; % default node is 9
            
            app.updatebptables();
            app.defaultPGcellarray();

            if isfield(app.userFolders,'config')&& ~isempty(app.userFolders.config)
               filename=[app.userFolders.code,app.defaultSuffix];
               app.defaultName=filename; % this is reduntant, could combine above
               filepath=app.userFolders.config;
               fullName=fullfile(filepath,filename);
               if exist(fullName,'file')==2  % make sure file exists before trying to open
                   app.OpenFileByName(filename,filepath);
               end
           end
        end

        % Button pushed function: DecodeFeaturesButton
        function DecodeFeaturesButtonPushed(app, event)
            myFeatures=app.BPconfigTable.Data{8,:};
            DecodeFeatures(myFeatures,app.UIFigure);

        end

        % Button pushed function: ReadBPSettingsButton
        function ReadBPSettingsButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.EMGTab;
            app.getBPdata();
        end

        % Button pushed function: ReadPGTimingButton
        function ReadPGTimingButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.StimTab;
            app.getPGdata();
        end

        % Button pushed function: NetworkOffButton
        function NetworkOffButtonPushed(app, event)
            app.nnp.enterWaiting();   % waiting mode
            success=app.nnp.networkOff();  % network off
            if success %true if successful
                app.NetworkLamp.Color=[0.90,0.90,0.90];
            else
                %need message
            end
        end

        % Button pushed function: WriteBPButton
        function WriteBPButtonPushed(app, event)
            app.Write2BP();
        end

        % Button pushed function: WritePGButton
        function WritePGButtonPushed(app, event)
            app.Write2PG();
        end

        % Button pushed function: ClearMessageButton
        function ClearMessageButtonPushed(app, event)
            app.ClearMessages();
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            % open mat file to read in remote module data
            % start with a mat file that I create externally to see if I have all the necessary variables saved.
            
            if app.checkDoSave()
                return
            end
            if isfield(app.userFolders,'config')&& ~isempty(app.userFolders.config)
                    fname=[app.userFolders.code,app.defaultSuffix];
                    defName=fullfile(app.userFolders.config,fname); % name with path                  
            else
                defName=app.defaultName; % name without path
            end
            [filename, filepath] = uigetfile('*.mat', 'Open Remote Module parameters mat File',defName);
             if filename == 0
                return
             else
                app.OpenFileByName(filename,filepath);
             end
            %{
            if filename == 0
                return
            else
                app.rmfilename = [pathname filename];
                app.FilenameEditField.Value=app.rmfilename;
            end
            
            %             app.rmParam = load(app.rmfilename);
            in=load(app.rmfilename);
            if ~isfield(in, 'rmParam') % this file does not contain the right structure
                % here I could test also test the number of fields in rmParam
                msgbox('Remote Module  mat file must contain the structure variable named "rmParam". Please try again.');
            else
                % Need similar checks as below (from scriptedit)
                %             if ~isfield(app.scripts, 'active') || ~isfield(app.scripts, 'library')
                %                 msgbox('Scripts mat file must contain variables named "active" and "library"')
                %                 if ~isnumeric(app.scripts.active) || ~isequal(size(app.scripts.active), [25,1])
                %                     msgbox('Scripts mat file must contain 25x1 vector named "active" ');
                %                 end
                %             end
                %             if ~isfield(app.scripts, 'order')
                %                 app.scripts.order = [];
                %             end
                app.rmParam=in.rmParam; %copy to app
                app.EMGNodeEditField.Value=app.rmParam.bpNode;
                app.StimNodesEditField.Value=num2str(app.rmParam.pgNodeList);
                app.rmParam.syncPeriod=app.rmParam.syncPeriod;
                app.SyncPeriodEditField.Value=app.rmParam.syncPeriod;
                app.updatebptables();
                app.updatepgtables();
                app.TopMessage('Data loaded from file');
                app.SyncPlotButtonPushed()
            end
            figure(app.UIFigure); % return focus
            %}
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
            % get a file name and save the data to that file
            % include a default file name.  How about rmParameters.mat
            defName=fullfile(app.userFolders.config,app.defaultName);
            [filename, pathname ] = uiputfile('*.mat', 'Save As Remote Module parameters mat File',defName);
            if filename == 0
                return
            else
                app.rmfilename = fullfile(pathname, filename);
                SaveMenuSelected(app, event);
                app.pathname=pathname;
                app.FilenameEditField.Value=app.rmfilename;
            end
            
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            %check for valid file name.   get new name if not valid
            % save data to file
            if isempty(app.rmfilename)
                SaveAsMenuSelected(app, event);
            else
                app.BPtables2Variables();
                app.PGtables2Variables();
                %                 bpSyncSchedule=app.rmParam.bpSyncSchedule;
                %                 bpSyncInt=app.rmParam.bpSyncInt;
                %                 bpCh1=app.rmParam.bpCh1;
                %                 bpCh2=app.rmParam.bpCh2;
                %                 pgNodeList=app.rmParam.pgNodeList;
                %                 pgTiming=app.rmParam.pgTiming;
                %                 bpNode=app.rmParam.bpNode;
                %                 save(app.rmfilename,'bpSyncSchedule','bpSyncInt','bpCh1','bpCh2','pgNodeList','pgTiming','bpNode');
                rmParam=app.rmParam; % save as local variable to save
                save(app.rmfilename,'rmParam'); % save the whole structure as a variable, rather than individual
                app.TopMessage('Data saved to file');
            end
            figure(app.UIFigure); % return focus
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.checkDoSave()
                return
            end
            % if I add rm help, here is where I would close those handles
            if ishandle(app.callingFigure) % check that is is valid
                figure(app.callingFigure); % bring to front
            end
            
            
            delete(app)
            
        end

        % Button pushed function: SyncPlotButton
        function SyncPlotButtonPushed(app, event)
            % SyncTimingPlot(app.rmParam);  %for now this is a separate plot, but plan to incorporate the plotting into this app
            app.PGtables2Variables();
            app.plotSyncTiming(); % this creates the x axis
            app.calcSyncPlot();
            app.plotSyncTiming(); %quick fix
        end

        % Value changed function: SyncPeriodEditField
        function SyncPeriodEditFieldValueChanged(app, event)
            value = app.SyncPeriodEditField.Value;
            app.rmParam.syncPeriod=value;
        end

        % Button pushed function: SaveBPButton
        function SaveBPButtonPushed(app, event)
%             Save BP settings as defaults
            app.nnp.enterWaiting();   % waiting mode
            success=app.nnp.networkOn();  % network on
            if success %true if successful
                app.NetworkLamp.Color=[0 1 0];
                app.TopMessage('Saving BP parameters...');
                pause(1); % wait for network to start
                
                % send NMT 0A to BP node
                node=uint8(app.EMGNodeEditField.Value);
                resp=app.nnp.saveOD(node);
                if resp==true
                    app.TopMessage('Saved BP defaults');
                else
                    app.TopMessage('Error saving BP defaults');
                end
            end
        end

        % Button pushed function: SavePGButton
        function SavePGButtonPushed(app, event)
%            Save PG settings as defaults 
            app.nnp.enterWaiting();   % waiting mode
            success=app.nnp.networkOn();  % network on
            if success %true if successful
                app.NetworkLamp.Color=[0 1 0];
                app.TopMessage('Saving PG defaults...');
                pause(1); % wait for network to start
                % network on
                % get the data, then update the UI tables
                app.rmParam.pgNodeList=str2num(app.StimNodesEditField.Value); % array of PG nodes
                numnodes=size(app.rmParam.pgNodeList,2);
                for n=1:numnodes
                    node=app.rmParam.pgNodeList(n);
                    resp=app.nnp.saveOD(node);
                    if resp==true
                        message=sprintf('Saved PG %d defaults',node);
                        app.TopMessage(message);
                    else
                        message=sprintf('Error saving PG %d defaults',node);
                        app.TopMessage(message);
                    end
                end
            end
        end

        % Value changed function: StimNodesEditField
        function StimNodesEditFieldValueChanged(app, event)
            value = app.StimNodesEditField.Value;
            app.rmParam.pgNodeList=str2num(value); 
            app.defaultPGcellarray();  % return to default when changed
        end

        % Button pushed function: ReadSyncButton
        function ReadSyncButtonPushed(app, event)
            rx=app.nnp.read(7,'1006',0,1,'uint32'); %Sync Period
            if ~isempty(rx)
                app.rmParam.syncPeriod=double(rx);
                app.SyncPeriodEditField.Value=double(rx);
                app.TopMessage('Read Sync Period');
            else
                app.TopMessage('Could not read Sync Period');
            end
        end

        % Button pushed function: WriteSyncButton
        function WriteSyncButtonPushed(app, event)
             result = app.nnp.write(7,'1006', 0, uint32(app.rmParam.syncPeriod), 'uint32');
            if ~isequal(result, 0)
                 app.TopMessage('Error writing Sync Period');
            else
                 app.TopMessage('Wrote Sync Period');
            end
        end

        % Button pushed function: SaveSyncButton
        function SaveSyncButtonPushed(app, event)
            app.nnp.enterWaiting();   % waiting mode              
                % send NMT 0A to PM
                resp=app.nnp.saveOD(7);
                if resp==true
                    app.TopMessage('Saved PM defaults');
                else
                    app.TopMessage('Error saving PM defaults');
                end
            
        end

        % Cell edit callback: BPscheduleTable
        function BPscheduleTableCellEdit(app, event)
           % this updates everything when one element is changed
           % a bit overkill but easier to write this way
            app.BPtables2Variables();
        end

        % Cell edit callback: BPconfigTable
        function BPconfigTableCellEdit(app, event)
            app.BPtables2Variables();
        end

        % Value changed function: CalcSendEditField
        function CalcSendEditFieldValueChanged(app, event)
            app.BPtables2Variables();
        end

        % Button pushed function: BPdefaultButton
        function BPdefaultButtonPushed(app, event)
            app.defaultBPSettings();
            app.SyncPlotButtonPushed();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Remote Module Setup';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.OpenMenu = uimenu(app.Menu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.Menu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.Menu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Text = 'SaveAs';
            app.TimingAxes = uiaxes(app.UIFigure);
            title(app.TimingAxes, 'Timing')
            xlabel(app.TimingAxes, 'ms')
            app.TimingAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EMGTab = uitab(app.TabGroup);
            app.EMGTab.Title = 'EMG';
            app.DecodeFeaturesButton = uibutton(app.EMGTab, 'push');
            app.DecodeFeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @DecodeFeaturesButtonPushed, true);
            app.DecodeFeaturesButton.Text = {'Decode'; 'Features'};
            app.SyncIntEditFieldLabel = uilabel(app.EMGTab);
            app.SyncIntEditFieldLabel.Text = 'Sync Int';
            app.SyncIntEditField = uieditfield(app.EMGTab, 'numeric');
            app.BPscheduleTable = uitable(app.EMGTab);
            app.BPscheduleTable.ColumnName = {'Ch 1'; 'Ch 2'};
            app.BPscheduleTable.RowName = {''};
            app.BPscheduleTable.ColumnEditable = true;
            app.BPscheduleTable.CellEditCallback = createCallbackFcn(app, @BPscheduleTableCellEdit, true);
            app.CalcSendEditFieldLabel = uilabel(app.EMGTab);
            app.CalcSendEditFieldLabel.Text = 'CalcSend';
            app.CalcSendEditField = uieditfield(app.EMGTab, 'numeric');
            app.CalcSendEditField.ValueChangedFcn = createCallbackFcn(app, @CalcSendEditFieldValueChanged, true);
            app.CalcSendEditField.Value = 31;
            app.BPconfigTable = uitable(app.EMGTab);
            app.BPconfigTable.ColumnName = {'Ch 1'; 'Ch 2'};
            app.BPconfigTable.RowName = {'Gain'; 'Depth'; 'Threshold ZX'; 'Threshold SSC'; 'MAV Gain'; 'MV Gain'; 'WFL Gain'; 'Features'};
            app.BPconfigTable.ColumnEditable = true;
            app.BPconfigTable.CellEditCallback = createCallbackFcn(app, @BPconfigTableCellEdit, true);
            app.BPdefaultButton = uibutton(app.EMGTab, 'push');
            app.BPdefaultButton.ButtonPushedFcn = createCallbackFcn(app, @BPdefaultButtonPushed, true);
            app.BPdefaultButton.Text = 'BP default';
            app.StimTab = uitab(app.TabGroup);
            app.StimTab.Title = 'Stim';
            app.PGsetupTable = uitable(app.StimTab);
            app.PGsetupTable.ColumnName = {'Stim1'; 'Stim2'; 'Stim3'; 'Stim4'; 'Stim5'; 'Stim6'};
            app.PGsetupTable.RowName = {'Node'; 'Sync Timing'; 'Sync Interval'; 'SyncPush'; 'AutoSyncTime'; 'MaxAutoSyncCount'; 'MinDischargeTime'; 'StimVOSx60'; 'MinVOSx60'; 'StimVOSsteps'; 'MinVOSsteps'; 'SetupAnode'; 'Channel IPI'};
            app.PGsetupTable.ColumnEditable = true;
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.ReadPGTimingButton = uibutton(app.Panel, 'push');
            app.ReadPGTimingButton.ButtonPushedFcn = createCallbackFcn(app, @ReadPGTimingButtonPushed, true);
            app.ReadPGTimingButton.Text = {'Read PG'; 'Timing'};
            app.ReadBPSettingsButton = uibutton(app.Panel, 'push');
            app.ReadBPSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ReadBPSettingsButtonPushed, true);
            app.ReadBPSettingsButton.Text = {'Read BP'; 'Settings'};
            app.EMGNodeEditFieldLabel = uilabel(app.Panel);
            app.EMGNodeEditFieldLabel.Text = 'EMG Node';
            app.EMGNodeEditField = uieditfield(app.Panel, 'numeric');
            app.EMGNodeEditField.Value = 9;
            app.StimNodesEditFieldLabel = uilabel(app.Panel);
            app.StimNodesEditFieldLabel.Text = 'Stim Nodes';
            app.StimNodesEditField = uieditfield(app.Panel, 'text');
            app.StimNodesEditField.ValueChangedFcn = createCallbackFcn(app, @StimNodesEditFieldValueChanged, true);
            app.StimNodesEditField.Value = '1 2 3 4 5 6';
            app.NetworkLampLabel = uilabel(app.Panel);
            app.NetworkLampLabel.Text = 'Network';
            app.NetworkLamp = uilamp(app.Panel);
            app.NetworkOffButton = uibutton(app.Panel, 'push');
            app.NetworkOffButton.ButtonPushedFcn = createCallbackFcn(app, @NetworkOffButtonPushed, true);
            app.NetworkOffButton.Text = 'Network Off';
            app.WriteBPButton = uibutton(app.Panel, 'push');
            app.WriteBPButton.ButtonPushedFcn = createCallbackFcn(app, @WriteBPButtonPushed, true);
            app.WriteBPButton.Text = 'Write BP';
            app.WritePGButton = uibutton(app.Panel, 'push');
            app.WritePGButton.ButtonPushedFcn = createCallbackFcn(app, @WritePGButtonPushed, true);
            app.WritePGButton.Text = 'Write PG';
            app.Messages = uitextarea(app.Panel);
            app.ClearMessageButton = uibutton(app.Panel, 'push');
            app.ClearMessageButton.ButtonPushedFcn = createCallbackFcn(app, @ClearMessageButtonPushed, true);
            app.ClearMessageButton.Text = 'clear';
            app.SyncPeriodEditFieldLabel = uilabel(app.Panel);
            app.SyncPeriodEditFieldLabel.Text = 'Sync Period';
            app.SyncPeriodEditField = uieditfield(app.Panel, 'numeric');
            app.SyncPeriodEditField.ValueChangedFcn = createCallbackFcn(app, @SyncPeriodEditFieldValueChanged, true);
            app.SyncPeriodEditField.Value = 60;
            app.SaveBPButton = uibutton(app.Panel, 'push');
            app.SaveBPButton.ButtonPushedFcn = createCallbackFcn(app, @SaveBPButtonPushed, true);
            app.SaveBPButton.Text = 'Save';
            app.SavePGButton = uibutton(app.Panel, 'push');
            app.SavePGButton.ButtonPushedFcn = createCallbackFcn(app, @SavePGButtonPushed, true);
            app.SavePGButton.Text = 'Save';
            app.WriteSwitchLabel = uilabel(app.Panel);
            app.WriteSwitchLabel.Text = 'Write';
            app.WriteSwitch = uiswitch(app.Panel, 'slider');
            app.WriteSwitch.Items = {'All', 'One'};
            app.WriteSwitch.Value = 'All';
            app.ColEditFieldLabel = uilabel(app.Panel);
            app.ColEditFieldLabel.Text = 'Col';
            app.ColEditField = uieditfield(app.Panel, 'numeric');
            app.ColEditField.Tooltip = {'Column number in node table'};
            app.ColEditField.Value = 1;
            app.SaveSyncButton = uibutton(app.Panel, 'push');
            app.SaveSyncButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSyncButtonPushed, true);
            app.SaveSyncButton.Text = 'Save';
            app.ReadSyncButton = uibutton(app.Panel, 'push');
            app.ReadSyncButton.ButtonPushedFcn = createCallbackFcn(app, @ReadSyncButtonPushed, true);
            app.ReadSyncButton.Text = 'Read Sync';
            app.WriteSyncButton = uibutton(app.Panel, 'push');
            app.WriteSyncButton.ButtonPushedFcn = createCallbackFcn(app, @WriteSyncButtonPushed, true);
            app.WriteSyncButton.Text = 'Write Sync';
            app.UserIDEditField = uieditfield(app.Panel, 'text');
            app.UserIDEditField.Editable = 'off';
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.SyncPlotButton = uibutton(app.UIFigure, 'push');
            app.SyncPlotButton.ButtonPushedFcn = createCallbackFcn(app, @SyncPlotButtonPushed, true);
            app.SyncPlotButton.Text = {'Sync'; 'Plot'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rmSetup(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end