classdef charger < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LAB2ChargerUIFigure         matlab.ui.Figure
        TemperatureCEditFieldLabel  matlab.ui.control.Label
        TemperatureEditField        matlab.ui.control.NumericEditField
        BatteryVEditFieldLabel      matlab.ui.control.Label
        BatteryEditField            matlab.ui.control.NumericEditField
        CurrentmAEditFieldLabel     matlab.ui.control.Label
        CurrentEditField            matlab.ui.control.NumericEditField
        FrequencySlider             matlab.ui.control.Slider
        FrequencyHzEditFieldLabel   matlab.ui.control.Label
        FrequencyEditField          matlab.ui.control.NumericEditField
        AutoUpdateCheckBox          matlab.ui.control.CheckBox
        PeriodsEditFieldLabel       matlab.ui.control.Label
        ScanPeriodEditField         matlab.ui.control.NumericEditField
        PeriodusLabel               matlab.ui.control.Label
        PeriodEditField             matlab.ui.control.NumericEditField
        CoilisOffButton             matlab.ui.control.StateButton
        MaxFreqEditField            matlab.ui.control.NumericEditField
        MinFreqEditField            matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        nnp = []; % Description
        updateTimer = [];
    end
    
    methods (Access = private)
        
        function result = cmdRespCharger(app, cmd, payload)
            if nargin<3
                payload = [];
            end
            result = [];
            s = app.nnp.port;
            if s.BytesAvailable
                fread(s, s.BytesAvailable); %clear buffer
            end
            
            fwrite(s, uint8([255 cmd length(payload)+3 payload]), 'uint8');
            t = tic;
            while s.BytesAvailable == 0 && toc(t)< app.nnp.timeout
                %delay loop
            end
            if s.BytesAvailable 
                resp = fread(s, s.BytesAvailable, 'uint8');
                if resp(1)==255 && length(resp)==resp(3) && resp(2) == 6
                    if length(resp)>=5
                        %TODO: make endianness consistent in LAB2
                        if cmd == 88 %temperature
                            result = double(typecast(uint8([resp(5), resp(4)]), 'uint16'));
                        else
                            result = double(typecast(uint8([resp(4), resp(5)]), 'uint16'));
                        end
                    elseif length(resp)>=4
                        result = double(resp(4));
                    end
                else
                    warning(['Bad Response: ' num2str(resp', ' %02X')]);
                end
            else
               warning('No Response');        
            end

        end
        
        function temp = convertTemperature(app, adc)
            ADC = [65535 23798	23483	23163	22839	22511	22179	21844	21506	21164	20821	20475	20127	19778	19427	19076	18725	18373	18021	17670	17320	16971	16623	16278	15933	15592	15253	14915	14586	14256	13931	13609	13291	12977	12668	12363	12063	11764	11473	11190	10909	10634 0];
            T   = [-Inf    10	11	12	13	14	15	16	17	18	19	20	21	22	23	24	25	26	27	28	29	30	31	32	33	34	35	36	37	38	39	40	41	42	43	44	45	46	47	48	49	50    Inf];
            temp = interp1(ADC,T,adc);
        end
        
        function updateDisplaysCallback(app, obj, event)
            resp = app.cmdRespCharger(89); %Connected
            if ~isempty(resp)
                if resp == 1
                    app.enableButtons(true);
                else
                    app.enableButtons(false);
                end
            end
            resp = app.cmdRespCharger(83); %Period
            if ~isempty(resp)
                app.PeriodEditField.Value = resp/8;
                app.FrequencyEditField.Value = 8000000/resp; %TODO convert from period bits
                
                limits = app.FrequencySlider.Limits;
                %if the read value is out of range of the slider, change
                %the slider limits
                if app.FrequencyEditField.Value > limits(2)
                    limits(2) = ceil(app.FrequencyEditField.Value);
                    app.MaxFreqEditField.Value = app.FrequencyEditField.Value;
                elseif app.FrequencyEditField.Value < limits(1)
                    limits(1) = floor(app.FrequencyEditField.Value);
                    app.MinFreqEditField.Value = app.FrequencyEditField.Value;
                end
                app.FrequencySlider.Limits = limits;
                
                app.FrequencySlider.Value = app.FrequencyEditField.Value;
            end
            resp = app.cmdRespCharger(88); %Temperature
            if ~isempty(resp)
                app.TemperatureEditField.Value = app.convertTemperature(resp); %convert using LU table
            end
            resp = app.cmdRespCharger(87); %Battery
            if ~isempty(resp)
                app.BatteryEditField.Value = resp/4096*2.5*5; %convert from adu to V
            end
            resp = app.cmdRespCharger(86); %Current
            if ~isempty(resp)
                app.CurrentEditField.Value = 1000*resp/4096*2.5; %convert from adu to mA
            end
        end
        
        function enableButtons(app, enable)
            app.FrequencyEditField.Enable = enable;
            app.TemperatureEditField.Enable = enable;
            app.BatteryEditField.Enable = enable;
            app.CurrentEditField.Enable = enable;
            app.FrequencySlider.Enable = enable;
            app.CoilisOffButton.Enable = enable;
        end
        
        function results = setTicks(app)
            limits = app.FrequencySlider.Limits;
            
            periodLow = floor(8000000/limits(2));
            periodHigh = ceil(8000000/limits(1));
            n = periodHigh-periodLow;
            fprintf('%5.0f\n', n)
            
            app.FrequencySlider.MajorTicksMode = 'manual';
            app.FrequencySlider.MinorTicksMode = 'manual';

            majorTick = max(1, round(n/10));
            app.FrequencySlider.MajorTicks = 8000000./(periodHigh:-majorTick:periodLow);
            if majorTick > 5
                app.FrequencySlider.MinorTicks = 8000000./(periodHigh:-majorTick/5:periodLow);
            else
                 app.FrequencySlider.MinorTicks = [];
            end
            labels = cell(length(app.FrequencySlider.MajorTicks),1);
            for i=1:length(app.FrequencySlider.MajorTicks)
                labels{i} = sprintf('%6.1f', app.FrequencySlider.MajorTicks(i));
            end
            app.FrequencySlider.MajorTickLabels = labels;
             
%             tickOptions = [1 2 5 10 20 50 100 500 1000 2000 5000 10000]
%             i = find(tickOptions >= majorTick, 1);
%             diffHigh = tickOptions(i)-majorTick;
%             diffLow = majorTick-tickOptions(i-1);
%             if diffHigh<diffLow
%                 majorTick = tickOptions(i)
%             else
%                 majorTick = tickOptions(i-1)
%             end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, NNP)
            if nargin<2
                NNP=[];
            end
            if isempty(NNP)
                app.nnp = NNPAPI();
                if isempty(app.nnp.port)
                    msgbox('No port chosen for NNP Access Point')
                    delete(app.nnp)
                    return
                end
            else
                app.nnp = NNP;
            end
            
            app.enableButtons(false);
            
            app.updateTimer = timer();
            app.updateTimer.Period = 1;
            app.updateTimer.ExecutionMode = 'fixedRate';
            app.updateTimer.BusyMode = 'drop';
            app.updateTimer.TimerFcn = @app.updateDisplaysCallback;
            start(app.updateTimer);
        end

        % Value changed function: AutoUpdateCheckBox
        function AutoUpdateCheckBoxValueChanged(app, event)
            if app.AutoUpdateCheckBox.Value
                start(app.updateTimer);
            else
                stop(app.updateTimer);
            end
            
        end

        % Close request function: LAB2ChargerUIFigure
        function LAB2ChargerUIFigureCloseRequest(app, event)
            try 
                if isequal(app.updateTimer.Running, 'on')
                    stop(app.updateTimer);
                end
            catch
            end
            delete(app);
            
            
        end

        % Value changed function: FrequencySlider
        function FrequencySliderValueChanged(app, event)
            freq = app.FrequencySlider.Value;
            payload = typecast(uint16(8000000/freq), 'uint8');
            resp = app.cmdRespCharger(82, payload); %Write Period
            if ~isequal(resp, uint16(8000000/freq))
                warning('coud not set coil period')
            end
        end

        % Value changed function: ScanPeriodEditField
        function ScanPeriodEditFieldValueChanged(app, event)
            if isequal(app.updateTimer.Running, 'on')
                stop(app.updateTimer);
                app.updateTimer.Period = app.ScanPeriodEditField.Value;
                start(app.updateTimer);
            else
                app.updateTimer.Period = app.ScanPeriodEditField.Value;
            end
        end

        % Value changed function: CoilisOffButton
        function CoilisOffButtonValueChanged(app, event)
            if app.CoilisOffButton.Value
                app.cmdRespCharger(84); %Start
                app.CoilisOffButton.Text = "Coil is On";
            else
                app.cmdRespCharger(85); %Stop
                app.CoilisOffButton.Text = "Coil is Off";
             end
        end

        % Value changed function: MaxFreqEditField
        function MaxFreqEditFieldValueChanged(app, event)
            value = app.MaxFreqEditField.Value;
            limits = app.FrequencySlider.Limits;
            if value <= limits(1)
                msgbox('Maximum slider value must be greater than existing minimum slider value')
            else
                limits(2) = value;
                %if the current slider value is above the new max, set it
                %to the new max
                if app.FrequencySlider.Value > value 
                    app.FrequencySlider.Value = value;
                    FrequencySliderValueChanged(app, event);
                end
                app.FrequencySlider.Limits = limits;
                app.setTicks();
            end
        end

        % Value changed function: MinFreqEditField
        function MinFreqEditFieldValueChanged(app, event)
            value = app.MinFreqEditField.Value;
            limits = app.FrequencySlider.Limits;
            if value >= limits(2)
                msgbox('Minimum slider value must be less than existing maximum slider value')
            else
                limits(1) = value;
                %if the current slider value is below the new min, set it
                %to the new min
                if app.FrequencySlider.Value < value 
                    app.FrequencySlider.Value = value;
                    FrequencySliderValueChanged(app, event);
                end
                app.FrequencySlider.Limits = limits;
                app.setTicks();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LAB2ChargerUIFigure = uifigure('Visible', 'off');
            app.LAB2ChargerUIFigure.Name = 'LAB2 Charger';
            app.LAB2ChargerUIFigure.CloseRequestFcn = createCallbackFcn(app, @LAB2ChargerUIFigureCloseRequest, true);
            app.TemperatureCEditFieldLabel = uilabel(app.LAB2ChargerUIFigure);
            app.TemperatureCEditFieldLabel.Text = 'Temperature (°C)';
            app.TemperatureEditField = uieditfield(app.LAB2ChargerUIFigure, 'numeric');
            app.TemperatureEditField.ValueDisplayFormat = '%11.1f';
            app.TemperatureEditField.Editable = 'off';
            app.BatteryVEditFieldLabel = uilabel(app.LAB2ChargerUIFigure);
            app.BatteryVEditFieldLabel.Text = 'Battery (V)';
            app.BatteryEditField = uieditfield(app.LAB2ChargerUIFigure, 'numeric');
            app.BatteryEditField.ValueDisplayFormat = '%11.2f';
            app.BatteryEditField.Editable = 'off';
            app.CurrentmAEditFieldLabel = uilabel(app.LAB2ChargerUIFigure);
            app.CurrentmAEditFieldLabel.Text = 'Current (mA)';
            app.CurrentEditField = uieditfield(app.LAB2ChargerUIFigure, 'numeric');
            app.CurrentEditField.ValueDisplayFormat = '%11.0f';
            app.CurrentEditField.Editable = 'off';
            app.FrequencySlider = uislider(app.LAB2ChargerUIFigure);
            app.FrequencySlider.Limits = [2000 4000];
            app.FrequencySlider.MajorTicks = [2000 2200 2400 2600 2800 3000 3200 3400 3600 3800 4000];
            app.FrequencySlider.MajorTickLabels = {'2000', '2200', '2400', '2600', '2800', '3000', '3200', '3400', '3600', '3800', '4000'};
            app.FrequencySlider.ValueChangedFcn = createCallbackFcn(app, @FrequencySliderValueChanged, true);
            app.FrequencySlider.MinorTicks = [2000 2020 2040 2060 2080 2100 2120 2140 2160 2180 2200 2220 2240 2260 2280 2300 2320 2340 2360 2380 2400 2420 2440 2460 2480 2500 2520 2540 2560 2580 2600 2620 2640 2660 2680 2700 2720 2740 2760 2780 2800 2820 2840 2860 2880 2900 2920 2940 2960 2980 3000 3020 3040 3060 3080 3100 3120 3140 3160 3180 3200 3220 3240 3260 3280 3300 3320 3340 3360 3380 3400 3420 3440 3460 3480 3500 3520 3540 3560 3580 3600 3620 3640 3660 3680 3700 3720 3740 3760 3780 3800 3820 3840 3860 3880 3900 3920 3940 3960 3980 4000];
            app.FrequencySlider.Value = 3500;
            app.FrequencyHzEditFieldLabel = uilabel(app.LAB2ChargerUIFigure);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.FrequencyEditField = uieditfield(app.LAB2ChargerUIFigure, 'numeric');
            app.FrequencyEditField.ValueDisplayFormat = '%7.1f';
            app.FrequencyEditField.Editable = 'off';
            app.AutoUpdateCheckBox = uicheckbox(app.LAB2ChargerUIFigure);
            app.AutoUpdateCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoUpdateCheckBoxValueChanged, true);
            app.AutoUpdateCheckBox.Text = 'Auto Update';
            app.AutoUpdateCheckBox.Value = true;
            app.PeriodsEditFieldLabel = uilabel(app.LAB2ChargerUIFigure);
            app.PeriodsEditFieldLabel.Text = 'Period (s)';
            app.ScanPeriodEditField = uieditfield(app.LAB2ChargerUIFigure, 'numeric');
            app.ScanPeriodEditField.Limits = [0.01 10];
            app.ScanPeriodEditField.ValueChangedFcn = createCallbackFcn(app, @ScanPeriodEditFieldValueChanged, true);
            app.ScanPeriodEditField.Value = 1;
            app.PeriodusLabel = uilabel(app.LAB2ChargerUIFigure);
            app.PeriodusLabel.Text = 'Period (us)';
            app.PeriodEditField = uieditfield(app.LAB2ChargerUIFigure, 'numeric');
            app.PeriodEditField.ValueDisplayFormat = '%11.1f';
            app.PeriodEditField.Editable = 'off';
            app.CoilisOffButton = uibutton(app.LAB2ChargerUIFigure, 'state');
            app.CoilisOffButton.ValueChangedFcn = createCallbackFcn(app, @CoilisOffButtonValueChanged, true);
            app.CoilisOffButton.Text = 'Coil is Off';
            app.MaxFreqEditField = uieditfield(app.LAB2ChargerUIFigure, 'numeric');
            app.MaxFreqEditField.Limits = [0 100000];
            app.MaxFreqEditField.ValueDisplayFormat = '%11.0f';
            app.MaxFreqEditField.ValueChangedFcn = createCallbackFcn(app, @MaxFreqEditFieldValueChanged, true);
            app.MaxFreqEditField.Value = 4000;
            app.MinFreqEditField = uieditfield(app.LAB2ChargerUIFigure, 'numeric');
            app.MinFreqEditField.Limits = [122 100000];
            app.MinFreqEditField.ValueDisplayFormat = '%11.0f';
            app.MinFreqEditField.ValueChangedFcn = createCallbackFcn(app, @MinFreqEditFieldValueChanged, true);
            app.MinFreqEditField.Value = 2000;
            % Show the figure after all components are created
            app.LAB2ChargerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = charger(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LAB2ChargerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LAB2ChargerUIFigure)
        end
    end
end