classdef BPSensorsRecording < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MESRawRecordingUIFigure      matlab.ui.Figure
        PMPCBSerialEditFieldLabel    matlab.ui.control.Label
        PMPCBSerialEditField         matlab.ui.control.NumericEditField
        PMAppRevisionLabel           matlab.ui.control.Label
        PMAppRevisionEditField       matlab.ui.control.NumericEditField
        NodeDropDownLabel            matlab.ui.control.Label
        NodeDropDown                 matlab.ui.control.DropDown
        BPPCBSerialEditFieldLabel    matlab.ui.control.Label
        BPPCBSerialEditField         matlab.ui.control.NumericEditField
        BPAppRevisionEditFieldLabel  matlab.ui.control.Label
        BPAppRevisionEditField       matlab.ui.control.NumericEditField
        PanelEMG                     matlab.ui.container.Panel
        EMGAxes                      matlab.ui.control.UIAxes
        DifferentialButton           matlab.ui.control.Button
        CommonButton                 matlab.ui.control.Button
        CMRRdBEditFieldLabel         matlab.ui.control.Label
        CMRREditField                matlab.ui.control.NumericEditField
        ChannelDropDown              matlab.ui.control.DropDown
        GainDropDown                 matlab.ui.control.DropDown
        VPPRTImVLabel                matlab.ui.control.Label
        DifferentialAmpEditField     matlab.ui.control.NumericEditField
        CommonAmpEditField           matlab.ui.control.NumericEditField
        ShowFFTCheckBox              matlab.ui.control.CheckBox
        ViewEMGButton                matlab.ui.control.StateButton
        FrequencyHzEditFieldLabel    matlab.ui.control.Label
        FrequencyEditField           matlab.ui.control.NumericEditField
        CommonModeInputsignalexpectedtobe1000xDifferentialLabel  matlab.ui.control.Label
        BaselineADULabel             matlab.ui.control.Label
        BaselineEditField            matlab.ui.control.NumericEditField
        PanelAccel                   matlab.ui.container.Panel
        AccelAxes                    matlab.ui.control.UIAxes
        StartButton                  matlab.ui.control.StateButton
        VNETVINCheckButton           matlab.ui.control.StateButton
        TemperatureCEditFieldLabel   matlab.ui.control.Label
        TemperatureEditField         matlab.ui.control.NumericEditField
        VINADUEditFieldLabel         matlab.ui.control.Label
        VINEditField                 matlab.ui.control.NumericEditField
        VICADUEditFieldLabel         matlab.ui.control.Label
        VICEditField                 matlab.ui.control.NumericEditField
        PMVNETVEditFieldLabel        matlab.ui.control.Label
        PMVNETEditField              matlab.ui.control.NumericEditField
        XLabel                       matlab.ui.control.Label
        XEditField                   matlab.ui.control.NumericEditField
        YLabel                       matlab.ui.control.Label
        YEditField                   matlab.ui.control.NumericEditField
        ZLabel                       matlab.ui.control.Label
        ZEditField                   matlab.ui.control.NumericEditField
        EditFieldLabel               matlab.ui.control.Label
        CntEditField                 matlab.ui.control.NumericEditField
        LogEMGLabel                  matlab.ui.control.Label
        LogEMGSwitch                 matlab.ui.control.Switch
        LogFilenameEditField         matlab.ui.control.EditField
        FolderButton                 matlab.ui.control.Button
        FolderEditField              matlab.ui.control.EditField
        NewLogFile                   matlab.ui.control.Button
        UserEditFieldLabel           matlab.ui.control.Label
        UserEditField                matlab.ui.control.NumericEditField
        ShowLastButton               matlab.ui.control.Button
        StimPanel                    matlab.ui.container.Panel
        NodeLabel                    matlab.ui.control.Label
        NodeEditField                matlab.ui.control.NumericEditField
        ChannelLabel                 matlab.ui.control.Label
        ChanEditField                matlab.ui.control.NumericEditField
        PWLabel                      matlab.ui.control.Label
        PWEditField                  matlab.ui.control.NumericEditField
        AmpLabel                     matlab.ui.control.Label
        PAEditField                  matlab.ui.control.NumericEditField
        IncludeStimButton            matlab.ui.control.StateButton
        IncludeStimLabel             matlab.ui.control.Label
        UITable                      matlab.ui.control.Table
        LoadfromFileButton           matlab.ui.control.Button
        VOSEditFieldLabel            matlab.ui.control.Label
        VOSEditField                 matlab.ui.control.NumericEditField
        ErrorLampLabel               matlab.ui.control.Label
        ErrorLamp                    matlab.ui.control.Lamp
        ErrorLabel                   matlab.ui.control.Label
        ErrorEditField               matlab.ui.control.EditField
        StopNetButton                matlab.ui.control.Button
        SetVOSminCheckBox            matlab.ui.control.CheckBox
        FullLogNameEditField         matlab.ui.control.EditField
        VnetEditFieldLabel           matlab.ui.control.Label
        VnetEditField                matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        nnp = [];
        node = [];
        nSamp = [];
        plotbuf = [];
        updateTimer = [];
        emgbuf = [];
        emgTimer = [];
        emgSamp =[];
        doUpdate = false;
        pauseTime = 0.01;
        logfileid=[];
        plotcycle=4; %plot every forth cycle, makeing this 5Hz update
        cycle=0;
        FullLogName=[];
    end
    
    properties (Access = private)
        updateCount = 1;
        savedSettings = [];
        savedTimeout =[];
        savedPMworInt=[];
        selpath=pwd;% get the current working directory
        LogFileName='TempLogFileName.bin'; % Save this to a temporary file
        LFileID=double([]); % File ID for Log File
        snode=1; % start with single channel.  Add vector later
        schan=1; % start with single channel.  Add vector later
        lastrowselected=0;
        stimOn = false;
        callingFigure=[]; %handle to calling figure
    end
    
    methods (Access = public)
        
        function updateEMG(app, obj, event)
            resp = app.nnp.read(7, '2053', 3, 'uint8');
            app.emgbuf(1:end-48) = app.emgbuf(49:end);
            if length(resp) == 49
                app.emgbuf(end-48+1:end) = resp(1:48)';
            else
                app.emgbuf(end-48+1:end) = nan(48,1);
            end
            app.EMGAxes.Children.YData = app.emgbuf;
        end
        
        function updateDisplays(app, obj, event)
            
            %Get Accel
            accel=[];
            resp = app.nnp.read(app.node, '2011', 1, 'uint8');
            if length(resp)==4
                if all(resp==255)
                    accel = [Inf Inf Inf];
                    mag = Inf;
                else
                    accel = [0 0 0];
                    for i=1:3
                        accel(i) = (double((bitshift(resp(i), -2))) - 32)/16; %convert to gs for plot
                    end
                    mag = norm(accel);
                end
                app.XEditField.Value = double(resp(1));
                app.YEditField.Value = double(resp(2));
                app.ZEditField.Value = double(resp(3));
                app.CntEditField.Value = double(resp(4));
            end
            
            if ~isempty(accel)
                app.plotbuf(1:end-1,:) = app.plotbuf(2:end,:);
                app.plotbuf(end,:) = [accel'; mag];
                for i = 1:4
                    app.AccelAxes.Children(i).YData = app.plotbuf(:,i);
                    
                end
            end
            
            
            drawnow;
            %pause(app.pauseTime);
            
            switch app.updateCount
                case 1
                    
                    %Get Temp
                    resp = app.nnp.getTemp(app.node);
                    %fprintf('\ntemp %f', resp);
                    if length(resp)==1
                        app.TemperatureEditField.Value = resp;
                        app.TemperatureEditField.BackgroundColor = [1 1 1];
                    else
                        app.TemperatureEditField.Value = Inf;
                        app.TemperatureEditField.BackgroundColor = [.5 .5 .5];
                    end
                    
                case 2
                    
                    %Get Diagnostics VIC
                    resp = double(app.nnp.read(app.node, '3410', 2, 'uint8'));  %VIC
                    %fprintf('\ndiagnostics %f', resp);
                    if length(resp) == 1
                        app.VICEditField.Value = resp;
                        app.VICEditField.BackgroundColor = [1 1 1];
                    else
                        app.VICEditField.Value = Inf;
                        app.VICEditField.BackgroundColor = [.5 .5 .5];
                    end
                case 3
                    
                    %Get Diagnostics VIN
                    resp = double(app.nnp.read(app.node, '3510', 2, 'uint8')); %VIN
                    %fprintf('\ndiagnostics %f', resp);
                    if length(resp) == 1
                        app.VINEditField.Value = resp;
                        app.VINEditField.BackgroundColor = [1 1 1];
                    else
                        app.VINEditField.Value = Inf;
                        app.VINEditField.BackgroundColor = [.5 .5 .5];
                    end
                    
            end
            app.updateCount = app.updateCount+1;
            if app.updateCount == 4
                app.updateCount = 1;
            end
            
            if app.doUpdate
                enable = 'on';
            else
                enable = 'off';
            end
            
            
            app.PMVNETEditField.Enable = enable;
            app.PMPCBSerialEditField.Enable = 'off';
            app.PMAppRevisionEditField.Enable = 'off';
            app.BPPCBSerialEditField.Enable = 'off';
            app.BPAppRevisionEditField.Enable = 'off';
            
            
            if ~app.doUpdate
                return
            end
            
            %pause(app.pauseTime);
            
            %Get PM VNET setting
            resp = app.nnp.getVNET();
            %fprintf('\nvnet %f', resp);
            if length(resp)==1
                app.PMVNETEditField.Value = resp;
                app.PMVNETEditField.BackgroundColor = [1 1 1];
            else
                app.PMVNETEditField.Value = Inf;
                app.PMVNETEditField.BackgroundColor = [.5 .5 .5];
                
            end
            
            app.doUpdate = false;
        end
        
        
        function updateEMGfile(app, obj, event)
            resp = app.nnp.read(7, '2053', 3, 'uint8');
            app.emgbuf(1:end-48) = app.emgbuf(49:end);
            if length(resp) == 49
                newdata = resp(1:48)';
            else
                newdata = nan(48,1); %
            end
            app.emgbuf(end-48+1:end) = newdata;
            % If I update every cycle, then I am updating the plot at 20
            % Hz.  Could probably update slower, like 10 or 5 hz.  To do
            % that I could have a running counter to determine when to
            % update.
            if app.cycle>=app.plotcycle
                app.EMGAxes.Children.YData = app.emgbuf; %update plot
                app.cycle=0;
            else
                app.cycle=app.cycle+1; %increment cycle and skip update
            end
            fwrite(app.LFileID,newdata,'single'); % log newdata to single to preserve nan and inf
        end
        
        function results = NewLogFileName(app)
            switch app.ChannelDropDown.Value
                case 'Channel 1'
                    ModeChanstring='EMG1';
                case 'Channel 2'
                    ModeChanstring='EMG2';
                case 'Offset 1'
                    ModeChanstring='Off1';
                case 'Offset 2'
                    ModeChanstring='Off2';
                otherwise
                    error('Oth')
            end
            gainstring=sprintf('_G%s',app.GainDropDown.Value);
            userstring=sprintf('P%d_',int16(app.UserEditField.Value));
            timestring=sprintf('_%02.0f',datevec(now));
            nodestring=sprintf('_N%d',int16(app.NodeEditField.Value));
            chanstring=sprintf('_C%d',int16(app.ChanEditField.Value));
            pwstring=sprintf('_pw%d',int16(app.PWEditField.Value));
            pastring=sprintf('_pa%d',int16(app.PAEditField.Value));
            %
            if app.IncludeStimButton.Value==true
                app.LogFilenameEditField.Value=[userstring,ModeChanstring,gainstring,timestring,nodestring,chanstring,pwstring,pastring,'.bin'];
            else
                app.LogFilenameEditField.Value=[userstring,ModeChanstring,gainstring,timestring,'.bin'];
            end
            %
            app.FullLogName=fullfile(app.FolderEditField.Value,app.LogFilenameEditField.Value);
            app.FullLogNameEditField.Value=app.FullLogName;
        end
        
        function results = NewLogFolderName(app)
            newpath = uigetdir(app.selpath,'Folder to store Raw EMG files');
            if ischar(newpath) % if canceled, this is a number 0
                if isfolder(newpath)  % can only use this with character array
                    app.selpath=newpath;  % update the path if a new one given
                    app.FolderEditField.Value=app.selpath;
                    app.FolderButton.BackgroundColor=[0.96,0.96,0.96]; % from [1,.9,.6]
                end
            end
            figure(app.MESRawRecordingUIFigure); % Restore focus to this window
            %
        end
    end
    
    methods (Access = private)
        
        function restoreRadio(app)
            app.nnp.setRadioSettings(app.savedSettings);
            app.nnp.timeout = app.savedTimeout;
            if app.savedPMworInt > 0
                app.nnp.worOn(app.savedPMworInt); %reenable WOR on PM if it was enabled
            end
        end
        
        function saveRadio(app)
            %read back AP settings
            settings = app.nnp.getRadioSettings();
            if isempty(settings)
                msgbox('could not read back AccessPoint Settings','Radio Configuration', 'error');
                return;
            else
                app.savedSettings = settings;
            end
            app.savedTimeout = app.nnp.timeout;
            
            %is PM WOR enabled?
            resp = double(app.nnp.read(7, '2600', 9, 'uint16'));
            if length(resp)==1
                app.savedPMworInt = resp;
            else
                msgbox('could not determine if PM has WOR enabled, will get set to default 20ms');
                app.savedPMworInt = 20;
            end
        end
        
        function startEMG(app)
            app.enableAll('off');
            app.saveRadio();
            % need to check whether the network is on first.
            
            %for max bandwidth, we need to eliminate radio retries, shorten timeouts, and turn of WOR
            app.nnp.setRadio('timeout', 10, 'retries', 0');
            app.nnp.worOff();
            app.setNetworkV();
            app.setNetworkOn();
            pause(.2); % allow time for all nodes to start up
            % If I need to set VOSmax, here is where I would do that
            if app.SetVOSminCheckBox.Value
                VOSminpar=app.VOSEditField.Value*60;
                if app.nnp.write(app.snode,'3210',3,VOSminpar,'uint16')~=0
                    msgbox(['enter set VOS ', num2str(app.snode), ' may have failed'])
                    app.VOSEditField.BackgroundColor=[1,0,0];
                else
                    app.VOSEditField.BackgroundColor=[1,1,1];
                end
            end
            if app.IncludeStimButton.Value==true
                app.startStim();
            end
            app.nnp.timeout = 0.04;
            
            app.EMGAxes.Color = [1 1 1];
            
            %Set diagnostic Mode and channel
            app.ChannelDropDown.Enable = 'off';
            app.GainDropDown.Enable = 'off';
            switch app.ChannelDropDown.Value
                case 'Channel 1'
                    ch=1;
                    mode=0;
                case 'Channel 2'
                    ch=2;
                    mode = 0;
                case 'Offset 1'
                    ch=1;
                    mode=2;
                case 'Offset 2'
                    ch=2;
                    mode = 2;
                otherwise
                    error('unknown dropdown value')
            end
            
            if app.nnp.write(app.node, '3000', 1, mode, 'uint8')~=0
                msgbox(['enter diagnostic mode ', num2str(mode), ' may have failed'])
            end
            
            if ~app.nnp.enterTestRaw(app.node, ch)
                msgbox('Could not enter Raw MES mode')
            end
            start(app.emgTimer); %not log data to file
        end
        
        function stopEMG(app)
            app.enableAll('on');
            stop(app.emgTimer);
            app.ChannelDropDown.Enable = 'on';
            app.GainDropDown.Enable = 'on';
            app.restoreRadio();
            if ~app.nnp.enterWaiting()
                msgbox('Could not enter waiting mode')
            end
        end
        
        function [amp, freq, baseline, sat] = calcFFT(app)
            Fs = 1000;            % Sampling frequency
            T = 1/Fs;             % Sampling period
            L = app.emgSamp;             % Length of signal
            t = (0:L-1)*T;        % Time vector
            r = 3;                % frequency tolerance around max freq (in Hz)
            
            good = ~isnan(app.emgbuf);
            
            if all(~good)
                msgbox('No EMG data', 'Data Warning', 'warn')
                amp = Inf;
                freq = Inf;
                baseline = Inf;
                sat = true;
                return;
            elseif any(~good)
                igood = find(diff([0; good; 0])==-1);  %transition from good to bad
                ibad = find(diff([0; good; 0])==1);  %transition from bad to good
                
                [L, j] = max(igood - ibad); %new Length of signal
                
                i = igood(j) - L; %starting index in app.emgbuf of longest non-nan section
                x = app.emgbuf(i:i+L-1);
                msgbox(sprintf('Empty sections were found in EMG data. Calculating outputs based on %u samples instead of %u',L, app.emgSamp), 'Data Warning', 'warn')
            else
                x=app.emgbuf;
            end
            Y = fft(x);
            P2 = abs(Y/L);
            P1 = P2(1:L/2+1);
            P1(2:end-1) = 2*P1(2:end-1);
            
            baseline = round(P1(1));
            
            [~, freq] = max(P1(2:end));
            
            if freq > r
                ampRaw = sum(P1(freq-r+1:freq+r+1));  %use max freq+/-3Hz
            else
                ampRaw = 0;
            end
            
            if ampRaw > 255
                sat = true;
                app.EMGAxes.Color = [1 .7 .7];
            else
                sat = false;
            end
            
            switch str2double(app.GainDropDown.Value)
                case 4,  gain = 300;
                case 11, gain = 1500;
                otherwise, msgbox('gain')
            end
            amp = ampRaw*3.3/256/gain*1000; %amplitude as Peak to Peak in mV


            if app.ShowFFTCheckBox.Value
                f = Fs*(0:(L/2))/L;
                figure;
                plot(f, P1);
                title('Single-Sided Amplitude Spectrum of EMG')
                xlabel('f (Hz)')
                ylabel('EMG Power (ADU)')
            end
        end
        
        function calcCMRR(app)
            if isfinite(app.DifferentialAmpEditField.Value) && isfinite(app.CommonAmpEditField.Value)
                app.CMRREditField.Value = 20*log10(app.DifferentialAmpEditField.Value/(app.CommonAmpEditField.Value/1000));
            end
        end
        
        function enableAll(app, enable)
            app.ViewEMGButton.Enable = enable;
            app.StartButton.Enable = enable;
            app.DifferentialButton.Enable = enable;
            app.CommonButton.Enable = enable;
            app.NodeDropDown.Enable = enable;
        end
        
        
        function  startStim(app)
            app.snode=app.NodeEditField.Value;
            app.schan=app.ChanEditField.Value;
            pw=app.PWEditField.Value;
            pa=app.PAEditField.Value*10;
            %             app.StimPanel.BackgroundColor=[.7,1,.5]; %change to see if it gets here
            app.StimPanel.BackgroundColor=[.7,1,.7];
            app.stimOn = true;
            result = app.nnp.write(app.snode, '3212', app.schan, uint8([pw pa])); % result for error check
        end
        
        
        function stopStim(app)
            % When stimulating and recording EMG, it is more likely for the
            % remove modules to miss the enterwaiting command.  Therefore I am
            % going to turn off the network when stim stops to ensure they stop
            if ~app.nnp.enterWaiting()  %needs to be in waiting before network can be turned off, resp=1 is success
                msgbox('Could not enter waiting');
            end
            pause(.1)
            if ~app.nnp.networkOff()
                msgbox('Could not turn network off');
            end
            while (isempty(app.nnp.getNetworkStatus())||app.nnp.getNetworkStatus()==1) %but this calls the function twice
                resp = questdlg('Could not confirm network status, Retry?');
                if isequal(resp, 'No')
                    return;
                end
                if ~app.nnp.enterWaiting()  %needs to be in waiting before network can be turned off, resp=1 is success
                    msgbox('Could not enter waiting');
                end
                pause(.1)
                if ~app.nnp.networkOff()
                    msgbox('Could not turn network off');
                end
            end
            app.stimOn=false;
            app.StimPanel.BackgroundColor=[.94,.94,.94];
            % The result never comes back 0  Need to figure out what it should
            % be.
            %            result = app.nnp.write(app.snode, '3212', app.schan, uint8([0 0])); %result will be [] if error
            %              app.StimPanel.BackgroundColor=[.4,.8,.8];% shade different to see if it gets here
            %             if ~isempty(result) % response is not empty
            %                 error = 0;
            %                 app.stimOn = false;
            %                 app.StimPanel.BackgroundColor=[.94,.94,.94];
            %                 app.clearError();
            %                app.PWEditField.BackgroundColor = [1, 1, 1];
            %                app.PAEditField.BackgroundColor = [1, 1, 1];
            %             else
            %
            %                 app.StimPanel.BackgroundColor=[.6,.2,.94];
            %                 result = app.nnp.write(app.snode, '3212', app.schan, uint8([0 0])); %set to zeros a second time
            %
            %                 if ~isempty(result) % response is not empty
            %                     error = 0;
            %                     app.stimOn = false;
            % %                     app.StimPanel.BackgroundColor=[.94,.94,.94];
            %                     app.clearError();
            %
            %                 else
            %                     app.setError();  %need to add this function
            %                     %indicate that values may not have updated
            %                     error = 1;
            %                     app.PWEditField.BackgroundColor = [1, 0, 0];
            %                     app.PAEditField.BackgroundColor = [1, 0, 0];
            %                    app.StimPanel.BackgroundColor=[.94,.94,.94];
            %                 end
            %             end
        end
        
        function clearError(app)
            app.ErrorLamp.Color = [0.9 0.9 0.9];
            app.ErrorEditField.Value = '';
            app.PWEditField.BackgroundColor = [1, 1, 1];
            app.PAEditField.BackgroundColor = [1, 1, 1];
            app.ErrorEditField.Value ='';
        end
        
        function setError(app)
            app.ErrorLamp.Color = [1 0 0];
            app.ErrorEditField.Value = app.nnp.lastError;
            %TODO:trigger light turning off after 1 s
        end
        
        function setNetworkV(app)
            if ~app.nnp.setVNET(app.VnetEditField.Value)  % this sets the network to 7.5v  I should not have this always set
                msgbox('could not set PM VNET');
                return;
            end
        end
        
        
        function setNetworkOn(app)
            if ~app.nnp.enterWaiting()
                msgbox('could not enter waiting on PM');
                return;
            end
            
            if ~app.nnp.networkOn()
                msgbox('could not turn on network');
                return;
            end
            
            pause(0.2)
            while app.nnp.getNetworkStatus()~=1
                resp = questdlg('Could not confirm network status, Retry?');
                if isequal(resp, 'No')
                    return;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin<2
                NNP=[];
            else
                NNP=varargin{1};
            end
            if isempty(NNP)
                app.nnp = NNPAPI();
                if isempty(app.nnp.port)
                    msgbox('No port chosen for NNP Access Point')
                    delete(app.nnp)
                    return
                end
            else
                app.nnp = NNP;
            end
            if nargin>2
                app.callingFigure=varargin{2};
            end

            
            app.PMPCBSerialEditField.Enable = 'on';
            app.PMAppRevisionEditField.Enable = 'on';
            
            %Get PM App Revs
            resp = app.nnp.getSwRev(7);
            if length(resp)==1
                app.PMAppRevisionEditField.Value = resp(1);
                app.PMAppRevisionEditField.BackgroundColor = [1 1 1];
            else
                app.PMAppRevisionEditField.Value = Inf;
                app.PMAppRevisionEditField.BackgroundColor = [1 0 0];
            end
            
            %pause(app.pauseTime);
            
            %Get PM Serial number
            resp = app.nnp.getSerial(7);
            if length(resp)==1
                app.PMPCBSerialEditField.Value = resp(1);
                app.PMPCBSerialEditField.BackgroundColor = [1 1 1];
            else
                app.PMPCBSerialEditField.Value = Inf;
                app.PMPCBSerialEditField.BackgroundColor = [1 0 0];
            end
            
            %pause(app.pauseTime);
            
            
            %pause(app.pauseTime);
            
            app.setNetworkV();
            
            %pause(app.pauseTime);
            
            app.setNetworkOn();
            
            app.VNETVINCheckButton.Enable = 'off';
            
            app.nSamp = 100;
            app.plotbuf = zeros(app.nSamp,4);
            plot(app.AccelAxes, 1:app.nSamp, app.plotbuf);
            app.AccelAxes.XLim = [0 app.nSamp];
            app.AccelAxes.YLim = [-2 2];
            app.AccelAxes.Children(1).Color = [1 0 0]; %X=red
            app.AccelAxes.Children(2).Color = [0 .5 0];%Y=green
            app.AccelAxes.Children(3).Color = [0 0 1]; %Z=blue
            app.AccelAxes.Children(4).Color = [0 0 0]; %Mag=black, dotted
            app.AccelAxes.Children(4).LineStyle =':';
            
            app.AccelAxes.Toolbar.Visible = 'off';  %to make the little toolbar at top right disappear.
            disableDefaultInteractivity(app.AccelAxes); %to prevent clicking points with cursor
            
            app.emgSamp = 1000;
            app.emgbuf = nan(app.emgSamp, 1);
            plot(app.EMGAxes, 1:app.emgSamp, app.emgbuf);
            app.EMGAxes.XLim = [0 1000];
            app.EMGAxes.YLim = [0 255];
            
            app.EMGAxes.Toolbar.Visible = 'off';  %to make the little toolbar at top right disappear.
            disableDefaultInteractivity(app.EMGAxes); %to prevent clicking points with cursor
            
            pause(0.1);
            app.NodeDropDownValueChanged();
            
            app.updateTimer = timer();
            app.updateTimer.Period = 0.25;
            app.updateTimer.ExecutionMode = 'fixedRate';
            app.updateTimer.BusyMode = 'drop';
            app.updateTimer.TimerFcn = @app.updateDisplays;
            
            
            app.emgTimer = timer();
            app.emgTimer.Period = 0.048;
            app.emgTimer.ExecutionMode = 'fixedRate';
            app.emgTimer.BusyMode = 'drop';
            app.emgTimer.TimerFcn = @app.updateEMG;
            %            app.emgTimer.TimerFcn = @app.updateEMGfile;
            app.FolderEditField.Value=app.selpath;  % start with current path
        end

        % Value changed function: NodeDropDown
        function NodeDropDownValueChanged(app, event)
            app.node = str2double(app.NodeDropDown.Value);
            
            
            app.BPPCBSerialEditField.Enable = 'on';
            app.BPAppRevisionEditField.Enable = 'on';
            
            %Check module type
            resp = app.nnp.getType(app.node);
            if ~isempty(resp) && ~isequal(resp, '2ch Biopotential Sensor BP-2')
                msgbox('Connected device is not a BP', 'BP Test', 'warn');
            end
            
            %Get RM App Revs
            resp = app.nnp.getSwRev(app.node);
            if length(resp)==1
                app.BPAppRevisionEditField.Value = resp;
                app.BPAppRevisionEditField.BackgroundColor = [1 1 1];
            else
                app.BPAppRevisionEditField.Value = Inf;
                app.BPAppRevisionEditField.BackgroundColor = [1 0 0];
            end
            
            %pause(app.pauseTime);
            
            %Get RM Serial number
            resp = app.nnp.getSerial(app.node);
            if length(resp)==1
                app.BPPCBSerialEditField.Value = resp;
                app.BPPCBSerialEditField.BackgroundColor = [1 1 1];
            else
                app.BPPCBSerialEditField.Value = Inf;
                app.BPPCBSerialEditField.BackgroundColor = [1 0 0];
            end


            %pause(app.pauseTime);
            
            
        end

        % Close request function: MESRawRecordingUIFigure
        function MESRawRecordingUIFigureCloseRequest(app, event)
            if ~isempty(app.updateTimer)
                if isa(app.updateTimer,'timer')  %these start as [] which cannot be validated or deleted
                    if isvalid(app.updateTimer)
                        stop(app.updateTimer);
                    end
                    delete(app.updateTimer);
                end
            end
            if ~isempty(app.emgTimer)
                if isa(app.emgTimer,'timer')  %these start as [] which cannot be validated or deleted
                    if isvalid(app.emgTimer)
                        stop(app.emgTimer);
                    end
                    delete(app.emgTimer);
                end
            end
            if ishandle(app.callingFigure) % check that is is valid
                figure(app.callingFigure); % bring to front
            end
            
            delete(app);
        end

        % Callback function
        function reinitializeButtonPushed(app, event)
            delete(app.nnp);
            app.startupFcn();
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            
            
            %Set diagnostic Mode
            if app.nnp.write(app.node, '3000', 1, 1, 'uint8')~=0
                msgbox('enter diagnostic mode 1 may have failed')
            end
            
            if app.StartButton.Value == 1
                app.enableAll('off');
                app.StartButton.Enable = 'on';
                if ~isempty(app.updateTimer)
                    app.StartButton.Text = 'Running. Press to Stop';
                    drawnow;
                    
                    app.doUpdate = true;
                    start(app.updateTimer)
                    app.VNETVINCheckButton.Enable = 'on';
                    return;
                else
                    msgbox('no timer to start')
                    
                end
            else
                app.enableAll('on');
                if ~isempty(app.updateTimer)
                    stop(app.updateTimer)
                else
                    msgbox('no timer to stop')
                end
            end
            pause(0.5);
            app.StartButton.Text = 'Start';
            app.VNETVINCheckButton.Enable = 'off';
            drawnow;
            
            %set gain to default
            app.nnp.setBPGains(app.node, 11, 11)
            
            
            [ch1, ch2] = app.nnp.getBPGains(app.node);
            if ch1 ~= ch2 || ch1 ~= 11
                msgbox('Gains not set')
            end
            
            %app.StartButton.Value = 0;
            
            
        end

        % Value changed function: VNETVINCheckButton
        function VNETVINCheckButtonValueChanged(app, event)
            if app.VNETVINCheckButton.Value
                if ~app.nnp.setVNET(8.5)
                    msgbox('action may have failed')
                end
            else
                if ~app.nnp.setVNET(7.5)
                    msgbox('action may have failed')
                end
            end
            app.doUpdate = true;
        end

        % Value changed function: ViewEMGButton
        function ViewEMGButtonValueChanged(app, event)
            if app.ViewEMGButton.Value
                if app.LogEMGSwitch.Value=="On"
                    app.NewLogFileButtonPushed;
                end
                
                app.startEMG();
                app.ViewEMGButton.Enable = 'on';
                app.GainDropDown.Enable = 'on'; % allow gain changes while viewing
            else
                app.stopEMG();
                if app.IncludeStimButton.Value==true
                    %                     pause(.2);
                    app.stopStim();
                end
            end
        end

        % Value changed function: GainDropDown
        function GainDropDownValueChanged(app, event)
            gain = str2double(app.GainDropDown.Value);
            if ~app.nnp.setBPGains(app.node, gain, gain)
                msgbox('Gains not set')
            end
            
        end

        % Button pushed function: DifferentialButton
        function DifferentialButtonPushed(app, event)
            app.DifferentialButton.BackgroundColor = [0 1 0];
            app.startEMG();
            pause(1.5)
            app.stopEMG();
            app.DifferentialButton.BackgroundColor = [.96 .96 .96];
            [amp, freq, baseline] = app.calcFFT();
            app.DifferentialAmpEditField.Value = amp;
            app.FrequencyEditField.Value = freq;
            app.BaselineEditField.Value = baseline;
            calcCMRR(app);
        end

        % Button pushed function: CommonButton
        function CommonButtonPushed(app, event)
            app.CommonButton.BackgroundColor = [0 1 0];
            app.startEMG();
            pause(1.5)
            app.stopEMG();
            app.CommonButton.BackgroundColor = [.96 .96 .96];
            [amp, freq, baseline] = app.calcFFT();
            app.CommonAmpEditField.Value = amp;
            app.FrequencyEditField.Value = freq;
            app.BaselineEditField.Value = baseline;
            calcCMRR(app);
        end

        % Button pushed function: FolderButton
        function FolderButtonPushed(app, event)
            NewLogFolderName(app);
            NewLogFileName(app);
        end

        % Button pushed function: NewLogFile
        function NewLogFileButtonPushed(app, event)
            % if current fle is open, want to close it first
            if exist('app.LFileID','var')==1  %file id exists
                if ~isempty(fopen(app.LFileID)) %file is open
                    fclose(app.LFileID); % close file
                end
            end
            % Get new file name
            NewLogFileName(app);
            % Open new file
            if exist('app.LFileID','var')==1  %file id exists
                if isempty(fopen(app.LFileID)) %file is not open
                    app.LFileID=fopen(app.FullLogName,'a'); % open file in append mode
                end
            else
                app.LFileID=fopen(app.FullLogName,'a'); % open file in append mode
            end
        end

        % Value changed function: LogEMGSwitch
        function LogEMGSwitchValueChanged(app, event)
            value = app.LogEMGSwitch.Value;
            if value=="On"
                app.emgTimer.TimerFcn = @app.updateEMGfile;
                %                 app.LogEMGSwitch.BackgroundColor='g';
                % add a check here for a valid folder and call
                % FolderButtonPushed if not
                if ~isfolder(app.FolderEditField.Value)
                    NewLogFolderName(app);
                end
                % Add check for valid file FID, and see if it is open
                % if not, then check for valid filename and create on if
                % not and open the file in append mode.
                if ~isfile(app.FullLogName)
                    NewLogFileName(app);    % create file name
                end
                % check whether file ID exists
                if exist('app.LFileID','var')==0
                    app.LFileID=fopen(app.FullLogName,'a'); % open file in append mode
                end
                % check whether file is already open
                if isempty(fopen(app.LFileID)) %file is not open
                    app.LFileID=fopen(app.FullLogName,'a'); % open file in append mode
                end
                % not create header
            else
                app.emgTimer.TimerFcn = @app.updateEMG; % this is set at startup
                %                 app.LogEMGSwitch.BackgroundColor=[.96,.96,.96];
                
                % Note that I don't close the file here because I may want
                % to append to it.
            end
        end

        % Button pushed function: ShowLastButton
        function ShowLastButtonPushed(app, event)
            % pass the file ID to a new window and display the raw data.
            % I if I could also pass filename, channel, gain, but start
            % with just the file ID and see if I can read the data first
            % and zoom properly.
            if ~isempty(app.FullLogName)
                app.FullLogNameEditField.Value=app.FullLogName;
            end
            if ~isempty(app.LFileID)
                fclose(app.LFileID);
            end
                        
            DisplayRawEMG(app.FullLogName);
        end

        % Value changed function: IncludeStimButton
        function IncludeStimButtonValueChanged(app, event)
            value = app.IncludeStimButton.Value;
            if value == true
                app.IncludeStimButton.Text='On';
                app.IncludeStimButton.BackgroundColor=[.6,1,.6];
            else
                app.IncludeStimButton.Text='Off';
                app.IncludeStimButton.BackgroundColor=[1,.6,.6];
            end
            
        end

        % Button pushed function: LoadfromFileButton
        function LoadfromFileButtonPushed(app, event)
            [filename, filepath] = uigetfile('.mat','Select Profiler History file');
            if filename
                ld = load([filepath filename]);
                data = ld.data;
                %Error checking on input file
                if size(data, 2) ~= 8
                    msgbox('Profiler history file does not have 8 columns of data', 'History File', 'error')
                    return
                end
                if size(data,1) == 0
                    msgbox('Profiler history file contains no rows', 'History File', 'error')
                    return
                end
                selector = cell2mat(data(:,1));
                if ~isempty(selector)
                    msgbox('Column 1 (Selector) must be all empty', 'History File', 'error')
                    return
                end
                active = cell2mat(data(:,2));
                if ~islogical(active)
                    msgbox('Column 2 (Active) must be all logicals', 'History File', 'error')
                    return
                end
                node = cell2mat(data(:,3));
                if any(~ismember(node, 1:127))
                    msgbox('Column 3 (Node) must be 1-127', 'History File', 'error')
                    return
                end
                ch = cell2mat(data(:,4));
                if any(~ismember(ch, 1:4))
                    msgbox('Column 4 (Ch) must be 1-4', 'History File', 'error')
                    return
                end
                pw = cell2mat(data(:,5));
                if any(~ismember(pw, 0:255))
                    msgbox('Column 5 (PW) must be 0-255', 'History File', 'error')
                    return
                end
                pa = cell2mat(data(:,6));
                if any(~ismember(pa, 0:200))
                    msgbox('Column 6 (PA) must be 0-200', 'History File', 'error')
                    return
                end
                app.UITable.Data = data;
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            row = event.Indices(1);
            col = event.Indices(2);
            app.lastrowselected=row;
            if col == 1 && app.stimOn == false
                data = app.UITable.Data(row,:);
                node = data{3};
                ch = data{4};
                pw = data{5};
                pa = data{6};
                
                app.NodeEditField.Value = node;
                app.ChanEditField.Value = ch;
                app.PAEditField.Value = pa/10;
                app.PWEditField.Value = pw;
            end
        end

        % Button pushed function: StopNetButton
        function StopNetButtonPushed(app, event)
            %emergency stop button in case the command gets stopped somewhere
            %in the Network.  Thei turns off the Network at the PM to stop
            %stim.  What it doesn't do is corect for radio or communication
            %errors between Matlab and the PM
            app.nnp.enterWaiting();  %needs to be in waiting before network can be turned off, resp=1 is success
            pause(.1);
            app.nnp.networkOff();
            app.stimOn=false;
            %             pause(.2);
            %             app.stopEMG();
            %             pause(.2);
            %             app.stopStim();
            app.StimPanel.BackgroundColor=[.94,.94,.94];
            % if I turn off the network, I also need to stop ViewEMG
            app.ViewEMGButton.Value=false;
            app.stopEMG();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MESRawRecordingUIFigure = uifigure('Visible', 'off');
            app.MESRawRecordingUIFigure.Name = 'MES Raw Recording';
            app.MESRawRecordingUIFigure.CloseRequestFcn = createCallbackFcn(app, @MESRawRecordingUIFigureCloseRequest, true);
            app.PMPCBSerialEditFieldLabel = uilabel(app.MESRawRecordingUIFigure);
            app.PMPCBSerialEditFieldLabel.Text = 'PM PCB Serial #';
            app.PMPCBSerialEditField = uieditfield(app.MESRawRecordingUIFigure, 'numeric');
            app.PMPCBSerialEditField.Editable = 'off';
            app.PMPCBSerialEditField.Value = Inf;
            app.PMAppRevisionLabel = uilabel(app.MESRawRecordingUIFigure);
            app.PMAppRevisionLabel.Text = 'PM App Revision';
            app.PMAppRevisionEditField = uieditfield(app.MESRawRecordingUIFigure, 'numeric');
            app.PMAppRevisionEditField.Editable = 'off';
            app.PMAppRevisionEditField.Value = Inf;
            app.NodeDropDownLabel = uilabel(app.MESRawRecordingUIFigure);
            app.NodeDropDownLabel.Text = 'Node';
            app.NodeDropDown = uidropdown(app.MESRawRecordingUIFigure);
            app.NodeDropDown.Items = {'1', '2', '3', '4', '5', '6', '9', '10'};
            app.NodeDropDown.ValueChangedFcn = createCallbackFcn(app, @NodeDropDownValueChanged, true);
            app.NodeDropDown.Value = '9';
            app.BPPCBSerialEditFieldLabel = uilabel(app.MESRawRecordingUIFigure);
            app.BPPCBSerialEditFieldLabel.Text = 'BP PCB Serial #';
            app.BPPCBSerialEditField = uieditfield(app.MESRawRecordingUIFigure, 'numeric');
            app.BPPCBSerialEditField.Editable = 'off';
            app.BPPCBSerialEditField.Value = Inf;
            app.BPAppRevisionEditFieldLabel = uilabel(app.MESRawRecordingUIFigure);
            app.BPAppRevisionEditFieldLabel.Text = 'BP App Revision';
            app.BPAppRevisionEditField = uieditfield(app.MESRawRecordingUIFigure, 'numeric');
            app.BPAppRevisionEditField.Editable = 'off';
            app.BPAppRevisionEditField.Value = Inf;
            app.PanelEMG = uipanel(app.MESRawRecordingUIFigure);
            app.EMGAxes = uiaxes(app.PanelEMG);
            title(app.EMGAxes, 'Raw EMG')
            xlabel(app.EMGAxes, 'time (ms)')
            ylabel(app.EMGAxes, 'EMG (ADU)')
            app.DifferentialButton = uibutton(app.PanelEMG, 'push');
            app.DifferentialButton.ButtonPushedFcn = createCallbackFcn(app, @DifferentialButtonPushed, true);
            app.DifferentialButton.Text = 'Differential';
            app.CommonButton = uibutton(app.PanelEMG, 'push');
            app.CommonButton.ButtonPushedFcn = createCallbackFcn(app, @CommonButtonPushed, true);
            app.CommonButton.Text = 'Common';
            app.CMRRdBEditFieldLabel = uilabel(app.PanelEMG);
            app.CMRRdBEditFieldLabel.Text = 'CMRR (dB)';
            app.CMRREditField = uieditfield(app.PanelEMG, 'numeric');
            app.CMRREditField.Editable = 'off';
            app.CMRREditField.Value = Inf;
            app.ChannelDropDown = uidropdown(app.PanelEMG);
            app.ChannelDropDown.Items = {'Channel 1', 'Channel 2', 'Offset 1', 'Offset 2'};
            app.ChannelDropDown.Value = 'Channel 1';
            app.GainDropDown = uidropdown(app.PanelEMG);
            app.GainDropDown.Items = {'Gain 190x (2)', 'Gain 300x (4)', 'Gain 475x (6)', 'Gain 750x (8)', 'Gain 1500x (11)', 'Gain 3000x (14)', 'Gain Level 20'};
            app.GainDropDown.ItemsData = {'2', '4', '6', '8', '11', '14', '20'};
            app.GainDropDown.ValueChangedFcn = createCallbackFcn(app, @GainDropDownValueChanged, true);
            app.GainDropDown.Value = '4';
            app.VPPRTImVLabel = uilabel(app.PanelEMG);
            app.VPPRTImVLabel.Text = 'VPP RTI (mV)';
            app.DifferentialAmpEditField = uieditfield(app.PanelEMG, 'numeric');
            app.DifferentialAmpEditField.Editable = 'off';
            app.DifferentialAmpEditField.Value = Inf;
            app.CommonAmpEditField = uieditfield(app.PanelEMG, 'numeric');
            app.CommonAmpEditField.Editable = 'off';
            app.CommonAmpEditField.Value = Inf;
            app.ShowFFTCheckBox = uicheckbox(app.PanelEMG);
            app.ShowFFTCheckBox.Text = 'Show FFT';
            app.ViewEMGButton = uibutton(app.PanelEMG, 'state');
            app.ViewEMGButton.ValueChangedFcn = createCallbackFcn(app, @ViewEMGButtonValueChanged, true);
            app.ViewEMGButton.Text = 'View EMG';
            app.FrequencyHzEditFieldLabel = uilabel(app.PanelEMG);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.FrequencyEditField = uieditfield(app.PanelEMG, 'numeric');
            app.FrequencyEditField.Editable = 'off';
            app.FrequencyEditField.Value = Inf;
            app.CommonModeInputsignalexpectedtobe1000xDifferentialLabel = uilabel(app.PanelEMG);
            app.CommonModeInputsignalexpectedtobe1000xDifferentialLabel.Text = 'Common Mode Input signal expected to be 1000x Differential Input Signal';
            app.BaselineADULabel = uilabel(app.PanelEMG);
            app.BaselineADULabel.Text = 'Baseline (ADU)';
            app.BaselineEditField = uieditfield(app.PanelEMG, 'numeric');
            app.BaselineEditField.Editable = 'off';
            app.BaselineEditField.Value = Inf;
            app.PanelAccel = uipanel(app.MESRawRecordingUIFigure);
            app.AccelAxes = uiaxes(app.PanelAccel);
            title(app.AccelAxes, 'Accelerometer')
            xlabel(app.AccelAxes, 'sample')
            ylabel(app.AccelAxes, 'acceleration (g''s)')
            app.StartButton = uibutton(app.PanelAccel, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.VNETVINCheckButton = uibutton(app.PanelAccel, 'state');
            app.VNETVINCheckButton.ValueChangedFcn = createCallbackFcn(app, @VNETVINCheckButtonValueChanged, true);
            app.VNETVINCheckButton.Text = 'VNET/VIN Check';
            app.TemperatureCEditFieldLabel = uilabel(app.PanelAccel);
            app.TemperatureCEditFieldLabel.Text = 'Temperature (°C)';
            app.TemperatureEditField = uieditfield(app.PanelAccel, 'numeric');
            app.TemperatureEditField.Editable = 'off';
            app.TemperatureEditField.Value = Inf;
            app.VINADUEditFieldLabel = uilabel(app.PanelAccel);
            app.VINADUEditFieldLabel.Text = 'VIN (ADU)';
            app.VINEditField = uieditfield(app.PanelAccel, 'numeric');
            app.VINEditField.Editable = 'off';
            app.VINEditField.Value = Inf;
            app.VICADUEditFieldLabel = uilabel(app.PanelAccel);
            app.VICADUEditFieldLabel.Text = 'VIC (ADU)';
            app.VICEditField = uieditfield(app.PanelAccel, 'numeric');
            app.VICEditField.Editable = 'off';
            app.VICEditField.Value = Inf;
            app.PMVNETVEditFieldLabel = uilabel(app.PanelAccel);
            app.PMVNETVEditFieldLabel.Text = 'PM VNET (V)';
            app.PMVNETEditField = uieditfield(app.PanelAccel, 'numeric');
            app.PMVNETEditField.Editable = 'off';
            app.PMVNETEditField.Value = Inf;
            app.XLabel = uilabel(app.PanelAccel);
            app.XLabel.Text = 'X';
            app.XEditField = uieditfield(app.PanelAccel, 'numeric');
            app.XEditField.Editable = 'off';
            app.XEditField.Value = Inf;
            app.YLabel = uilabel(app.PanelAccel);
            app.YLabel.Text = 'Y';
            app.YEditField = uieditfield(app.PanelAccel, 'numeric');
            app.YEditField.Editable = 'off';
            app.YEditField.Value = Inf;
            app.ZLabel = uilabel(app.PanelAccel);
            app.ZLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.PanelAccel, 'numeric');
            app.ZEditField.Editable = 'off';
            app.ZEditField.Value = Inf;
            app.EditFieldLabel = uilabel(app.PanelAccel);
            app.EditFieldLabel.Text = '#';
            app.CntEditField = uieditfield(app.PanelAccel, 'numeric');
            app.CntEditField.Editable = 'off';
            app.CntEditField.Value = Inf;
            app.LogEMGLabel = uilabel(app.MESRawRecordingUIFigure);
            app.LogEMGLabel.Text = 'LogEMG';
            app.LogEMGSwitch = uiswitch(app.MESRawRecordingUIFigure, 'slider');
            app.LogEMGSwitch.ValueChangedFcn = createCallbackFcn(app, @LogEMGSwitchValueChanged, true);
            app.LogFilenameEditField = uieditfield(app.MESRawRecordingUIFigure, 'text');
            app.LogFilenameEditField.Editable = 'off';
            app.FolderButton = uibutton(app.MESRawRecordingUIFigure, 'push');
            app.FolderButton.ButtonPushedFcn = createCallbackFcn(app, @FolderButtonPushed, true);
            app.FolderButton.Text = 'Folder';
            app.FolderEditField = uieditfield(app.MESRawRecordingUIFigure, 'text');
            app.FolderEditField.Editable = 'off';
            app.NewLogFile = uibutton(app.MESRawRecordingUIFigure, 'push');
            app.NewLogFile.ButtonPushedFcn = createCallbackFcn(app, @NewLogFileButtonPushed, true);
            app.NewLogFile.Text = 'New File';
            app.UserEditFieldLabel = uilabel(app.MESRawRecordingUIFigure);
            app.UserEditFieldLabel.Text = 'User';
            app.UserEditField = uieditfield(app.MESRawRecordingUIFigure, 'numeric');
            app.UserEditField.ValueDisplayFormat = '%d';
            app.UserEditField.Value = 125;
            app.ShowLastButton = uibutton(app.MESRawRecordingUIFigure, 'push');
            app.ShowLastButton.ButtonPushedFcn = createCallbackFcn(app, @ShowLastButtonPushed, true);
            app.ShowLastButton.Text = 'Show Last';
            app.StimPanel = uipanel(app.MESRawRecordingUIFigure);
            app.StimPanel.Title = 'Stimulation';
            app.NodeLabel = uilabel(app.StimPanel);
            app.NodeLabel.Text = 'Node';
            app.NodeEditField = uieditfield(app.StimPanel, 'numeric');
            app.NodeEditField.Value = 1;
            app.ChannelLabel = uilabel(app.StimPanel);
            app.ChannelLabel.Text = 'Channel';
            app.ChanEditField = uieditfield(app.StimPanel, 'numeric');
            app.ChanEditField.Value = 1;
            app.PWLabel = uilabel(app.StimPanel);
            app.PWLabel.Text = 'PW';
            app.PWEditField = uieditfield(app.StimPanel, 'numeric');
            app.AmpLabel = uilabel(app.StimPanel);
            app.AmpLabel.Text = 'Amp';
            app.PAEditField = uieditfield(app.StimPanel, 'numeric');
            app.PAEditField.Value = 20;
            app.IncludeStimButton = uibutton(app.StimPanel, 'state');
            app.IncludeStimButton.ValueChangedFcn = createCallbackFcn(app, @IncludeStimButtonValueChanged, true);
            app.IncludeStimButton.Text = 'Off';
            app.IncludeStimLabel = uilabel(app.StimPanel);
            app.IncludeStimLabel.Text = {'Include'; 'Stim'};
            app.UITable = uitable(app.StimPanel);
            app.UITable.ColumnName = {'Select'; 'Active'; 'Node'; 'Ch'; 'PW'; 'PA'; 'Reg'; 'Notes'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = [false true true true true true false true];
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.LoadfromFileButton = uibutton(app.StimPanel, 'push');
            app.LoadfromFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfromFileButtonPushed, true);
            app.LoadfromFileButton.Text = {'Load'; 'from File'};
            app.VOSEditFieldLabel = uilabel(app.StimPanel);
            app.VOSEditFieldLabel.Text = 'v';
            app.VOSEditField = uieditfield(app.StimPanel, 'numeric');
            app.VOSEditField.Value = 34;
            app.ErrorLampLabel = uilabel(app.StimPanel);
            app.ErrorLampLabel.Text = 'Error';
            app.ErrorLamp = uilamp(app.StimPanel);
            app.ErrorLabel = uilabel(app.StimPanel);
            app.ErrorLabel.Text = 'Error';
            app.ErrorEditField = uieditfield(app.StimPanel, 'text');
            app.StopNetButton = uibutton(app.StimPanel, 'push');
            app.StopNetButton.ButtonPushedFcn = createCallbackFcn(app, @StopNetButtonPushed, true);
            app.StopNetButton.Text = 'StopNet';
            app.SetVOSminCheckBox = uicheckbox(app.StimPanel);
            app.SetVOSminCheckBox.Text = 'Set VOSmin';
            app.FullLogNameEditField = uieditfield(app.MESRawRecordingUIFigure, 'text');
            app.VnetEditFieldLabel = uilabel(app.MESRawRecordingUIFigure);
            app.VnetEditFieldLabel.Text = 'Vnet';
            app.VnetEditField = uieditfield(app.MESRawRecordingUIFigure, 'numeric');
            app.VnetEditField.Value = 8.5;
            % Show the figure after all components are created
            app.MESRawRecordingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BPSensorsRecording(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MESRawRecordingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MESRawRecordingUIFigure)
        end
    end
end