classdef ConvertFile46to16 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConvertOD46Files         matlab.ui.Figure
        Title                    matlab.ui.control.Label
        Description1             matlab.ui.control.Label
        LogFilenameEditField     matlab.ui.control.EditField
        FolderButton             matlab.ui.control.Button
        FolderEditField          matlab.ui.control.EditField
        NewLogFile               matlab.ui.control.Button
        TypeEditFieldLabel       matlab.ui.control.Label
        TypeEditField            matlab.ui.control.EditField
        TypeLabel                matlab.ui.control.Label
        TypeDD                   matlab.ui.control.DropDown
        NewHeaderTable           matlab.ui.control.Table
        OpenButton               matlab.ui.control.Button
        ConvertButton            matlab.ui.control.Button
        tworows                  matlab.ui.control.Table
        ColsDropDownLabel        matlab.ui.control.Label
        ColsDropDown             matlab.ui.control.DropDown
        CSVPanel                 matlab.ui.container.Panel
        SourceColEditFieldLabel  matlab.ui.control.Label
        SourceColEditField       matlab.ui.control.NumericEditField
        SkipHeaderSwitchLabel    matlab.ui.control.Label
        SkipHeaderSwitch         matlab.ui.control.Switch
        SourceHeaderTable        matlab.ui.control.Table
        OneStepButton            matlab.ui.control.Button
        CreatedEditFieldLabel    matlab.ui.control.Label
        CreatedEditField         matlab.ui.control.EditField
        MessageEditFieldLabel    matlab.ui.control.Label
        MessageEditField         matlab.ui.control.EditField
    end

    
    properties (Access = private)
        Property % Description
    end
    
    properties (Access = public)
        LogFolder=[]; % Description
        LogFileName=[];
        LogFullName=[];
        NewFilename=[];
        LFileID=0;
        NewFileID=0;
        Tlength=0;
        Twidth=0;
        DisplayTable=[];
        NewHeader={};
        NewArray=[];
        DataMatrix=[];
    end
    
    methods (Access = public)
        
        function NewLogFileName(app)
            app.MessageEditField.Value="";
            [fn, pn]=uigetfile('*.*','Choose Processed EMG file',app.LogFolder);
            if (fn~=0)
                [~,~,ext]=fileparts(fn);               
                app.TypeEditField.Value=ext;
                app.LogFolder=pn;
                app.FolderEditField.Value=pn;
                app.LogFileName=fn;
                app.LogFilenameEditField.Value=fn;
                app.LogFullName=fullfile(pn, fn);
            else
                app.MessageEditField.Value="Cancelled Open";
            end
        end
        %-----------------------------------------------------------------------------------
        function results = makeHeader(app)
            results={'Counter', 'WFL1','Scaled1','ASF1','WFL2','Scaled2','ASF2','Diff5M1','HLCnt1','MES1SLD','Diff5M2','HLCnt2','MES2SLD','ComHand','LockStatus','PatHand'};
                end
                
        %---------------------------------------------------------------------------------
        function  NewLogFolderName(app)
            newpath = uigetdir(app.LogFolder,'Folder to Read Files to Convert');
            if ischar(newpath) % if canceled, this is a number 0
                if isfolder(newpath)  % can only use this with character array
                    app.LogFolder=newpath;  % update the path if a new one given
                    app.FolderEditField.Value=newpath;
                    app.FolderButton.BackgroundColor=[0.96,0.96,0.96]; % from [1,.9,.6]
                end
            end
            figure(app.ConvertOD46Files); % Restore focus to this window
            %
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.NewHeader=app.makeHeader();
            app.NewHeaderTable.Data=app.NewHeader';
            app.LogFolder=pwd;
        end

        % Button pushed function: FolderButton
        function FolderButtonPushed(app, event)
            NewLogFolderName(app);
            NewLogFileName(app);
        end

        % Button pushed function: NewLogFile
        function NewLogFileButtonPushed(app, event)
           % if current fle is open, want to close it first
            if exist('app.LFileID','var')==1  %file id exists
                if ~isempty(fopen(app.LFileID)) %file is open
                    fclose(app.LFileID); % close file
                end
            end
            % Get new file name
            NewLogFileName(app);
            % Open new file
            if exist('app.LFileID','var')==1  %file id exists
                if isempty(fopen(app.LFileID)) %file is not open
                    app.LFileID=fopen(app.LogFullName,'r'); % open file in read only mode
                end
            else
                app.LFileID=fopen(app.LogFullName,'r'); % open file in read only mode
            end 
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            switch app.TypeDD.Value
                case 'bin'
                    rowread=str2num(app.ColsDropDown.Value);
                    app.LFileID=fopen(app.LogFullName);
                    app.DataMatrix=fread(app.LFileID,[rowread,inf],'double'); %
                    fclose(app.LFileID);
                    app.tworows.Data=app.DataMatrix(1:2,:)'; %display two rows in column so I can see if they should be 47 or 97
                    
                case 'csv'
                    app.DisplayTable=readtable(app.DisplayFile);
                    app.Tlength=size(app.DisplayTable,1);
                    app.Twidth=size(app.DisplayTable,2);
            end
        end

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
          switch app.TypeDD.Value
                case 'bin'
                    newrows=[2,20,17,30,21,18,31,34,32,36,35,33,37,6,16,22];
                    app.NewArray=app.DataMatrix(newrows,:)';
                    app.DisplayTable=array2table(app.NewArray,'VariableNames',app.NewHeader);
                case 'csv'
                    
          end
          [pn,fn,ext]=fileparts(app.LogFullName);
          newfn=strcat(fn,'_16c.csv');
          newfile=fullfile(pn,newfn);
          writetable(app.DisplayTable,newfile);
          app.CreatedEditField.Value=newfn;
        end

        % Button pushed function: OneStepButton
        function OneStepButtonPushed(app, event)
            app.NewLogFileButtonPushed;
            app.OpenButtonPushed;
            app.ConvertButtonPushed;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConvertOD46Files = uifigure('Visible', 'off');
            app.ConvertOD46Files.Name = 'UI Figure';
            app.Title = uilabel(app.ConvertOD46Files);
            app.Title.Text = 'Convert OD display from 46 column files to 16 rearranged for MESProcessedDisplay app';
            app.Description1 = uilabel(app.ConvertOD46Files);
            app.Description1.Tooltip = {'How do I know whether 47 or 097?'};
            app.Description1.Text = {'I could read the type from the file itself, but start with dropdown menu.  '; 'Files from ODBlockDisplay were 47 or 97 col, type single binary with first column as time.  '; 'Data from MESProcessed app were 46 column CSV'};
            app.LogFilenameEditField = uieditfield(app.ConvertOD46Files, 'text');
            app.LogFilenameEditField.Editable = 'off';
            app.FolderButton = uibutton(app.ConvertOD46Files, 'push');
            app.FolderButton.ButtonPushedFcn = createCallbackFcn(app, @FolderButtonPushed, true);
            app.FolderButton.Text = 'Folder';
            app.FolderEditField = uieditfield(app.ConvertOD46Files, 'text');
            app.FolderEditField.Editable = 'off';
            app.NewLogFile = uibutton(app.ConvertOD46Files, 'push');
            app.NewLogFile.ButtonPushedFcn = createCallbackFcn(app, @NewLogFileButtonPushed, true);
            app.NewLogFile.Text = 'Get File';
            app.TypeEditFieldLabel = uilabel(app.ConvertOD46Files);
            app.TypeEditFieldLabel.Text = 'Type';
            app.TypeEditField = uieditfield(app.ConvertOD46Files, 'text');
            app.TypeLabel = uilabel(app.ConvertOD46Files);
            app.TypeLabel.Text = 'Type';
            app.TypeDD = uidropdown(app.ConvertOD46Files);
            app.TypeDD.Items = {'bin', 'csv'};
            app.TypeDD.Value = 'bin';
            app.NewHeaderTable = uitable(app.ConvertOD46Files);
            app.NewHeaderTable.ColumnName = {'NewHeaders'};
            app.NewHeaderTable.RowName = {};
            app.NewHeaderTable.ColumnSortable = true;
            app.NewHeaderTable.ColumnEditable = true;
            app.OpenButton = uibutton(app.ConvertOD46Files, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.ConvertButton = uibutton(app.ConvertOD46Files, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Text = 'Convert';
            app.tworows = uitable(app.ConvertOD46Files);
            app.tworows.ColumnName = {'Time'; 'Counter'};
            app.tworows.RowName = {};
            app.tworows.ColumnSortable = true;
            app.ColsDropDownLabel = uilabel(app.ConvertOD46Files);
            app.ColsDropDownLabel.Text = 'Cols';
            app.ColsDropDown = uidropdown(app.ConvertOD46Files);
            app.ColsDropDown.Items = {'47', '97'};
            app.ColsDropDown.Value = '47';
            app.CSVPanel = uipanel(app.ConvertOD46Files);
            app.CSVPanel.Title = 'CSVPanel';
            app.SourceColEditFieldLabel = uilabel(app.CSVPanel);
            app.SourceColEditFieldLabel.Text = 'SourceCol';
            app.SourceColEditField = uieditfield(app.CSVPanel, 'numeric');
            app.SkipHeaderSwitchLabel = uilabel(app.CSVPanel);
            app.SkipHeaderSwitchLabel.Tooltip = {'Skip header row'};
            app.SkipHeaderSwitchLabel.Text = 'SkipHeader';
            app.SkipHeaderSwitch = uiswitch(app.CSVPanel, 'slider');
            app.SourceHeaderTable = uitable(app.CSVPanel);
            app.SourceHeaderTable.ColumnName = {'Headers'};
            app.SourceHeaderTable.RowName = {};
            app.OneStepButton = uibutton(app.ConvertOD46Files, 'push');
            app.OneStepButton.ButtonPushedFcn = createCallbackFcn(app, @OneStepButtonPushed, true);
            app.OneStepButton.Tooltip = {''};
            app.OneStepButton.Text = 'Get Open Convert One Step';
            app.CreatedEditFieldLabel = uilabel(app.ConvertOD46Files);
            app.CreatedEditFieldLabel.Text = 'Created';
            app.CreatedEditField = uieditfield(app.ConvertOD46Files, 'text');
            app.MessageEditFieldLabel = uilabel(app.ConvertOD46Files);
            app.MessageEditFieldLabel.Text = 'Message';
            app.MessageEditField = uieditfield(app.ConvertOD46Files, 'text');
            % Show the figure after all components are created
            app.ConvertOD46Files.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConvertFile46to16

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ConvertOD46Files)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConvertOD46Files)
        end
    end
end