classdef DisplayProcessedEMG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EMGDisplay                matlab.ui.Figure
        EMGAxes                   matlab.ui.control.UIAxes
        ScaledAxes                matlab.ui.control.UIAxes
        TabGroup                  matlab.ui.container.TabGroup
        LogicalTab                matlab.ui.container.Tab
        D5Axes                    matlab.ui.control.UIAxes
        TwitchAxes                matlab.ui.control.UIAxes
        HLAxes                    matlab.ui.control.UIAxes
        ProportionalTab           matlab.ui.container.Tab
        ASFAxes                   matlab.ui.control.UIAxes
        EMGAxes_2                 matlab.ui.control.UIAxes
        ScaledAxes_2              matlab.ui.control.UIAxes
        TabGroup_2                matlab.ui.container.TabGroup
        LogicalTab_2              matlab.ui.container.Tab
        D5Axes_2                  matlab.ui.control.UIAxes
        TwitchAxes_2              matlab.ui.control.UIAxes
        HLAxes_2                  matlab.ui.control.UIAxes
        ProportionalTab_2         matlab.ui.container.Tab
        ASFAxes_2                 matlab.ui.control.UIAxes
        ComAxes_2                 matlab.ui.control.UIAxes
        PlotLimitsPanel           matlab.ui.container.Panel
        setXButton                matlab.ui.control.Button
        setYButton                matlab.ui.control.Button
        ToolsButton               matlab.ui.control.StateButton
        GridsButton               matlab.ui.control.StateButton
        Sync1Button               matlab.ui.control.Button
        Sync2Button               matlab.ui.control.Button
        DataPanel                 matlab.ui.container.Panel
        FolderEditField           matlab.ui.control.EditField
        GetFileButton             matlab.ui.control.Button
        DataLabel                 matlab.ui.control.Label
        DisplayFileEditField      matlab.ui.control.EditField
        CommentsPanel             matlab.ui.container.Panel
        ReadButton                matlab.ui.control.Button
        CommentsTextArea          matlab.ui.control.TextArea
        CommentFileNameEditField  matlab.ui.control.EditField
        CommentsTextAreaLabel     matlab.ui.control.Label
        MessagesPanel             matlab.ui.container.Panel
        Messages                  matlab.ui.control.TextArea
        ClearMessageButton        matlab.ui.control.Button
    end

    
    properties (Access = public)

        % buffers 1
        plotbuf = [];
        emgbuf = [];
        scaledbuf = [];
        d5buf = [];
        twitchbuf = [];
        hlbuf=[];
        asfbuf=[];
        
        % buffers 2
        plotbuf2 = [];
        emgbuf2 = [];
        scaledbuf2 = [];
        d5buf2 = [];
        twitchbuf2 = [];
        hlbuf2=[];
        asfbuf2=[];
        combuf2=[];
   
        plotcycle=4; %plot every forth cycle, makeing this 5Hz update
        cycle=0;
        
        emgsamp = 50; % start with 5 seconds, may be too slow
        tdata=[20,17,34,36,32,30];
        tdataboth=[19,16,33,35,31,29;20,17,34,36,32,30]; %two rows
        ODval=double(zeros(1,16));
        lastcounter=0;
        plotthis=0;
        oldtime=[];
        templockdisable=0; % when I disable, I want to save the old value
        DisplayFile=[];
        DisplayTable=[];
        DFileID=[];
        Tlength=[];
    end
    
    properties (Access = private)

        selpath=pwd;% get the current working directory
        LogFileName='LogFileNamedefault.csv'; % default file name
        CommentFileName='CommentFileNamedefault.txt'; % default file name
        FileNameDialog; % Dialog to return log and comment and log file full paths
        LFileID=double([]); % File ID for Log File
        CommentFileID=-1; %Comment file ID


        pTw=[];
        pWFL=[]; % WFL plot
        pWFLoff=[]; % WFL offset plot
        pWFLgain=[]; % WFL gain plot
        
        pWFL2=[]; % WFL plot
        pWFLoff2=[]; % WFL offset plot
        pWFLgain2=[]; % WFL gain plot
        
        pD51=[]; % D5 plot 1
        pD5high1=[]; % D5 high for yline of D5 plot 1
        pD5low1=[]; % D5 low for yline of D5 plot 1
        pD52=[];  % D5 plot 2
        pD5high2=[]; % D5 high for yline of D5 plot 2
        pD5low2=[]; % D5 low for yline of D5 plot 2
        defaultXlimits=[0,50]; %set in one place to make easier to change
        newXlimits=[0,50];
    end
    
    methods (Access = public)
        % the next step is to record the data.
        % create a window to enter the name to log the data
        % in that window select a folder and user id, then auto create the
        % name
        
        
         end
    
    methods (Access = private)
        
         
        function TopMessage(app,message)
            app.Messages.Value=vertcat(message, string(app.Messages.Value)); % put new message on top
        end
        
        
        function ClearMessage(app)
            app.Messages.Value=''; % clear messages
        end
    
        function changeDefaultInteractions(app)
                % the default interactions stink, so change the defaults, then
                % you can enable and disable them.
                app.EMGAxes.Interactions =[zoomInteraction panInteraction];              
                app.ScaledAxes.Interactions =[zoomInteraction panInteraction ];
                app.D5Axes.Interactions =[zoomInteraction panInteraction];
                app.TwitchAxes.Interactions =[zoomInteraction panInteraction ];
                app.HLAxes.Interactions =[zoomInteraction panInteraction ];
                app.ASFAxes.Interactions =[zoomInteraction panInteraction ];                
                app.EMGAxes_2.Interactions =[zoomInteraction panInteraction ];
                app.ScaledAxes_2.Interactions =[zoomInteraction panInteraction ];
                app.D5Axes_2.Interactions =[zoomInteraction panInteraction ];
                app.TwitchAxes_2.Interactions =[zoomInteraction panInteraction ];
                app.HLAxes_2.Interactions =[zoomInteraction panInteraction ];
                app.ASFAxes_2.Interactions =[zoomInteraction panInteraction ];
                app.ComAxes_2.Interactions =[zoomInteraction panInteraction ]; %app.ComAxes_2
                 
                app.EMGAxes.Interactions(1).Dimensions='y'; %set the zoom to y only              
                app.ScaledAxes.Interactions(1).Dimensions='y'; %set the zoom to y only
                app.D5Axes.Interactions(1).Dimensions='y'; %set the zoom to y only
                app.TwitchAxes.Interactions(1).Dimensions='y'; %set the zoom to y only
                app.HLAxes.Interactions(1).Dimensions='y'; %set the zoom to y only
                app.ASFAxes_2.Interactions(1).Dimensions='y'; %set the zoom to y only                
                app.EMGAxes_2.Interactions(1).Dimensions='y'; %set the zoom to y only
                app.ScaledAxes_2.Interactions(1).Dimensions='y'; %set the zoom to y only
                app.D5Axes_2.Interactions(1).Dimensions='y'; %set the zoom to y only
                app.TwitchAxes_2.Interactions(1).Dimensions='y'; %set the zoom to y only
                app.HLAxes_2.Interactions(1).Dimensions='y'; %set the zoom to y only
                app.ComAxes_2.Interactions(1).Dimensions='y'; %set the zoom to y only
                
                app.EMGAxes.Interactions(2).Dimensions='y'; %set the zoom to y only              
                app.ScaledAxes.Interactions(2).Dimensions='y'; %set the zoom to y only
                app.D5Axes.Interactions(2).Dimensions='y'; %set the zoom to y only
                app.TwitchAxes.Interactions(2).Dimensions='y'; %set the zoom to y only
                app.HLAxes.Interactions(2).Dimensions='y'; %set the zoom to y only
                app.ASFAxes_2.Interactions(2).Dimensions='y'; %set the zoom to y only                
                app.EMGAxes_2.Interactions(2).Dimensions='y'; %set the zoom to y only
                app.ScaledAxes_2.Interactions(2).Dimensions='y'; %set the zoom to y only
                app.D5Axes_2.Interactions(2).Dimensions='y'; %set the zoom to y only
                app.TwitchAxes_2.Interactions(2).Dimensions='y'; %set the zoom to y only
                app.HLAxes_2.Interactions(2).Dimensions='y'; %set the zoom to y only
                app.ComAxes_2.Interactions(2).Dimensions='y'; %set the zoom to y only
        end
    
        function disableGraphInteractions(app)
            disableDefaultInteractivity(app.EMGAxes); %to prevent clicking points with cursor
            disableDefaultInteractivity(app.ScaledAxes); %to prevent clicking points with cursor
            disableDefaultInteractivity(app.D5Axes); %to prevent clicking points with cursor
            disableDefaultInteractivity(app.TwitchAxes); %to prevent clicking points with cursor
            disableDefaultInteractivity(app.HLAxes); %to prevent clicking points with cursor
            disableDefaultInteractivity(app.ASFAxes); %to prevent clicking points with cursor
            
            disableDefaultInteractivity(app.EMGAxes_2); %to prevent clicking points with cursor
            disableDefaultInteractivity(app.ScaledAxes_2); %to prevent clicking points with cursor
            disableDefaultInteractivity(app.D5Axes_2); %to prevent clicking points with cursor
            disableDefaultInteractivity(app.TwitchAxes_2); %to prevent clicking points with cursor
            disableDefaultInteractivity(app.HLAxes_2); %to prevent clicking points with cursor
             disableDefaultInteractivity(app.ASFAxes_2); %to prevent clicking points with cursor
            disableDefaultInteractivity(app.ComAxes_2); %to prevent clicking points with cursor
        end
        
        function enableGraphInteractions(app)
            enableDefaultInteractivity(app.EMGAxes); % 
            enableDefaultInteractivity(app.ScaledAxes); % 
            enableDefaultInteractivity(app.D5Axes); % 
            enableDefaultInteractivity(app.TwitchAxes); % 
            enableDefaultInteractivity(app.HLAxes); % 
            enableDefaultInteractivity(app.ASFAxes); % 
            
            enableDefaultInteractivity(app.EMGAxes_2); % 
            enableDefaultInteractivity(app.ScaledAxes_2); % 
            enableDefaultInteractivity(app.D5Axes_2); % 
            enableDefaultInteractivity(app.TwitchAxes_2); % 
            enableDefaultInteractivity(app.HLAxes_2); % 
            enableDefaultInteractivity(app.ASFAxes_2); % 
            enableDefaultInteractivity(app.ComAxes_2); %
        end


        function showhideAxesToolbars(app,sh)
            % make this a show/hide function by adding string input on or off
            app.EMGAxes.Toolbar.Visible = sh;  %to make the little toolbar at top right disappear.
            app.ScaledAxes.Toolbar.Visible = sh;  %to make the little toolbar at top right disappear.
            app.D5Axes.Toolbar.Visible = sh;  %to make the little toolbar at top right disappear.
            app.TwitchAxes.Toolbar.Visible = sh;  %to make the little toolbar at top right disappear
            app.HLAxes.Toolbar.Visible = sh;  %to make the little toolbar at top right disappear.
            app.ASFAxes.Toolbar.Visible = sh;  %to make the little toolbar at top right disappear.
            app.EMGAxes_2.Toolbar.Visible = sh;  %to make the little toolbar at top right disappear.
            
            app.ScaledAxes_2.Toolbar.Visible = sh;  %to make the little toolbar at top right disappear.
            app.D5Axes_2.Toolbar.Visible = sh;  %to make the little toolbar at top right disappear.
            app.TwitchAxes_2.Toolbar.Visible = sh;  %to make the little toolbar at top right disappear.
            app.HLAxes_2.Toolbar.Visible = sh;  %to make the little toolbar at top right disappear.
            app.ASFAxes_2.Toolbar.Visible = sh;  %to make the little toolbar at top right disappear.
            app.ComAxes_2.Toolbar.Visible = sh;  %to make the little toolbar at top right disappear.    
        end
        
%         function setDefaultXlimits(app)
%             app.EMGAxes.XLim = app.defaultXlimits;
%             app.ScaledAxes.XLim = app.defaultXlimits;
%             app.D5Axes.XLim = app.defaultXlimits;
%             app.TwitchAxes.XLim = app.defaultXlimits;
%             app.HLAxes.XLim = app.defaultXlimits;
%             app.ASFAxes.XLim = app.defaultXlimits;
%             app.EMGAxes_2.XLim = app.defaultXlimits;
%             app.ScaledAxes_2.XLim = app.defaultXlimits;
%             app.D5Axes_2.XLim = app.defaultXlimits;
%             app.TwitchAxes_2.XLim = app.defaultXlimits;
%             app.HLAxes_2.XLim = app.defaultXlimits;
%             app.ASFAxes_2.XLim = app.defaultXlimits;
%             app.ComAxes_2.XLim = app.defaultXlimits;
%         end
    
        function setDefaultYlimits(app)
            % use this to reset the defaults
            % need some sort of configuration table so that these could be
            % changed to new defaults if you wanted to.  Maybe a separate
            % window you could call?
            app.EMGAxes.YLim = [0 255];
            app.ScaledAxes.YLim = [0 255];
            app.D5Axes.YLim = [-1000 1000];            
            app.TwitchAxes.YLim = [0 5];            
            app.HLAxes.YLim = [0 25];           
            app.ASFAxes.YLim = [0 255];            
            app.EMGAxes_2.YLim = [0 255];            
            app.ScaledAxes_2.YLim = [0 255];            
            app.D5Axes_2.YLim = [-1000 1000];            
            app.TwitchAxes_2.YLim = [0 5];            
            app.HLAxes_2.YLim = [0 25];            
            app.ASFAxes_2.YLim = [0 255];            
            app.ComAxes_2.YLim = [0 255];
        end
        
        
        function results = makeHeader(app)
            % replace below with the new header I creaete
            %    results='Date,PMPowerAv,PMPowerInst,Vsys,Vnet,NetOn,PMCANErr,APRSSI,PMtemp,PMmode,PMErr';
%             results=['01-Counter,02-WFL1,03-Scaled1,'...
%                 '04-ASF1,05-WFL2,06-Scaled2,07-ASF2,08-Diff5M1,'...
%                 '09-HLCount1,10-MES1SLD,11-Diff5M2,12-HLCount2,'...
%                 '13-MES2SLD,14-ComHand,15-LockStatus,16-PatHand'];
            results=['Counter, WFL1,Scaled1,'...
                'ASF1,WFL2,Scaled2,ASF2,Diff5M1,'...
                'HLCnt1,MES1SLD,Diff5M2,HLCnt2,'...
                'MES2SLD,ComHand,LockStatus,PatHand'];
            results=sprintf([results,'\n']);  %add carriage return
        end
        
        function GetNewFileNameFromDialog(app)
            % get the current file names
            user=app.UserEditField.Value;
            folder=app.FolderEditField.Value;
            
            % call the dialog box with input values
            app.FileNameDialog=GetFileNamesDialogBox(app,user,folder);   
        end
        
        function DisplayPlots(app)
            %First set of plots
%             app.emgbuf = nan(app.emgsamp, 1);
%             app.scaledbuf = nan(app.emgsamp, 1);
%             app.d5buf = nan(app.emgsamp, 1);
%             app.twitchbuf = nan(app.emgsamp, 1);
%             app.hlbuf = nan(app.emgsamp, 1);
%             app.asfbuf = nan(app.emgsamp, 1);
            % length of plot is ap.Tlength
            % 
            xd=1:app.Tlength;  %common Xdata=1:Tlength
            app.defaultXlimits=[0,app.Tlength];
            
            app.pWFL=plot(app.EMGAxes, xd, app.DisplayTable.WFL1);
%             offset = app.Poffset.Value;
%             upper = (255/app.Pgain.Value)+offset;
%             app.pWFLoff=yline(app.EMGAxes,offset,'r');
%             app.pWFLgain=yline(app.EMGAxes,upper,'r');            
            plot(app.ScaledAxes,xd, app.DisplayTable.Scaled1);         
            app.pD51=plot(app.D5Axes, xd, app.DisplayTable.Diff5M1);
%             high=app.D5high.Value;
%             low=app.D5high.Value*-1;
%             app.pD5high1=yline(app.D5Axes,high,'r');
%             app.pD5low1=yline(app.D5Axes,low,'r');            
            app.pTw=plot(app.TwitchAxes, xd, app.DisplayTable.MES1SLD);           
            plot(app.HLAxes, xd, app.DisplayTable.HLCnt1);       
            plot(app.ASFAxes, xd, app.DisplayTable.ASF1);       

            % end first set of plots
            
            %Second set of plots
%             app.emgbuf2 = nan(app.emgsamp, 1);
%             app.scaledbuf2 = nan(app.emgsamp, 1);
%             app.d5buf2 = nan(app.emgsamp, 1);
%             app.twitchbuf2 = nan(app.emgsamp, 1);
%             app.hlbuf2 = nan(app.emgsamp, 1);
%             app.asfbuf2 = nan(app.emgsamp, 1);
%             app.combuf2 = nan(app.emgsamp, 1);         

            app.pWFL2=plot(app.EMGAxes_2, xd, app.DisplayTable.WFL2);
%             offset = app.Poffset.Value;
%             upper = (255/app.Pgain.Value)+offset;
%             app.pWFLoff2=yline(app.EMGAxes_2,offset,'r');
%             app.pWFLgain2=yline(app.EMGAxes_2,upper,'r');            
            plot(app.ScaledAxes_2, xd, app.DisplayTable.Scaled2);         
            app.pD52=plot(app.D5Axes_2, xd, app.DisplayTable.Diff5M2);
%             high=app.D5high_2.Value;
%             low=app.D5high_2.Value*-1;
%             app.pD5high2=yline(app.D5Axes_2,high,'r');
%             app.pD5low2=yline(app.D5Axes_2,low,'r');            
            plot(app.TwitchAxes_2, xd, app.DisplayTable.MES2SLD);       
            plot(app.HLAxes_2, xd, app.DisplayTable.HLCnt2);          
            plot(app.ASFAxes_2, xd, app.DisplayTable.ASF2);       
            plot(app.ComAxes_2, xd, app.DisplayTable.ComHand);     
            % end of second set of plots
            
             app.SetAllXlimits(app.defaultXlimits);
             app.setDefaultYlimits(); %need function to reset plots to default Ylim since Home
             app.changeDefaultInteractions();
             app.showhideAxesToolbars('off'); % since the button defaults to off  
        end
        
        function SetAllXlimits(app,XL)
            app.EMGAxes.XLim = XL;
            app.ScaledAxes.XLim = XL;
            app.D5Axes.XLim = XL;
            app.TwitchAxes.XLim = XL;
            app.HLAxes.XLim = XL;
            app.ASFAxes.XLim = XL;
            app.EMGAxes_2.XLim = XL;
            app.ScaledAxes_2.XLim = XL;
            app.D5Axes_2.XLim = XL;
            app.TwitchAxes_2.XLim = XL;
            app.HLAxes_2.XLim = XL;
            app.ASFAxes_2.XLim = XL;
            app.ComAxes_2.XLim = XL;
            % need to create meaningful ticks and labels
            % sample rate is 100ms, so break the XL into 6 parts and set labels.
            totalsec=XL(2)-XL(1); % total seconds for range
            ticksec=totalsec/5; % divide by 5 to get the increments
            tick=round(XL(1)+ticksec*[0:5],2);   % will want to round to nearest 100ms
            for i=1:6
                ticklabel{i}=num2str(tick(i),'%0.1f');
            end
            % only want tick labels on EMG, HL, ASF.  Others blank
            app.EMGAxes.XTick = tick;
            app.ScaledAxes.XTick = tick;
            app.D5Axes.XTick = tick;
            app.TwitchAxes.XTick = tick;
            app.HLAxes.XTick = tick;
            app.ASFAxes.XTick = tick;
            app.EMGAxes_2.XTick = tick;
            app.ScaledAxes_2.XTick = tick;
            app.D5Axes_2.XTick = tick;
            app.TwitchAxes_2.XTick = tick;
            app.HLAxes_2.XTick = tick;
            app.ASFAxes_2.XTick = tick;
            app.ComAxes_2.XTick = tick;
            % only want tick labels on EMG, HL, ASF.  Others blank
            app.EMGAxes.XTickLabel = ticklabel;
            app.HLAxes.XTickLabel = ticklabel;
            app.ASFAxes.XTickLabel = ticklabel;
            app.EMGAxes_2.XTickLabel = ticklabel;
            app.HLAxes_2.XTickLabel = ticklabel;
            app.ASFAxes_2.XTickLabel = ticklabel;
            app.ComAxes_2.XTickLabel = ticklabel;
            %
            app.ScaledAxes.XTickLabel = [];
            app.D5Axes.XTickLabel = [];
            app.TwitchAxes.XTickLabel = [];
            app.ScaledAxes_2.XTickLabel = [];
            app.D5Axes_2.XTickLabel = [];
            app.TwitchAxes_2.XTickLabel = [];
        end
        
        function showhideAxesGrids(app,sh)
             % make this a show/hide function by adding string input on or off
            app.EMGAxes.XGrid = sh;  % show or hide the X grid
            app.ScaledAxes.XGrid = sh;  
            app.D5Axes.XGrid = sh;
            app.TwitchAxes.XGrid = sh;
            app.HLAxes.XGrid = sh;
            app.ASFAxes.XGrid = sh;
            app.EMGAxes_2.XGrid = sh;
            
            app.ScaledAxes_2.XGrid = sh;
            app.D5Axes_2.XGrid = sh;
            app.TwitchAxes_2.XGrid = sh;
            app.HLAxes_2.XGrid = sh;
            app.ASFAxes_2.XGrid = sh;
            app.ComAxes_2.XGrid = sh;  
            
            app.EMGAxes.YGrid = sh;  % show or hide the Y grid
            app.ScaledAxes.YGrid = sh;  
            app.D5Axes.YGrid = sh;
            app.TwitchAxes.YGrid = sh;
            app.HLAxes.YGrid = sh;
            app.ASFAxes.YGrid = sh;
            app.EMGAxes_2.YGrid = sh;
            
            app.ScaledAxes_2.YGrid = sh;
            app.D5Axes_2.YGrid = sh;
            app.TwitchAxes_2.YGrid = sh;
            app.HLAxes_2.YGrid = sh;
            app.ASFAxes_2.YGrid = sh;
            app.ComAxes_2.YGrid = sh;  
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.selpath=pwd;% get the current working directory

            app.TabGroup_2.SelectedTab=app.ProportionalTab_2;   % MES2 defaults to proportional
            app.TabGroup.SelectedTab=app.LogicalTab;  % MES1 defaults to logical control
            
            %First set of plots
            app.emgbuf = nan(app.emgsamp, 1);
            app.scaledbuf = nan(app.emgsamp, 1);
            app.d5buf = nan(app.emgsamp, 1);
            app.twitchbuf = nan(app.emgsamp, 1);
            app.hlbuf = nan(app.emgsamp, 1);
            app.asfbuf = nan(app.emgsamp, 1);
            
            
            app.pWFL=plot(app.EMGAxes, 1:app.emgsamp, app.emgbuf);
     
            plot(app.ScaledAxes, 1:app.emgsamp, app.scaledbuf);            
            app.pD51=plot(app.D5Axes, 1:app.emgsamp, app.d5buf);
         
            app.pTw=plot(app.TwitchAxes, 1:app.emgsamp, app.twitchbuf);            
            plot(app.HLAxes, 1:app.emgsamp, app.hlbuf);            
            plot(app.ASFAxes, 1:app.emgsamp, app.asfbuf);         

            % end first set of plots
            
            %Second set of plots
            app.emgbuf2 = nan(app.emgsamp, 1);
            app.scaledbuf2 = nan(app.emgsamp, 1);
            app.d5buf2 = nan(app.emgsamp, 1);
            app.twitchbuf2 = nan(app.emgsamp, 1);
            app.hlbuf2 = nan(app.emgsamp, 1);
            app.asfbuf2 = nan(app.emgsamp, 1);
            app.combuf2 = nan(app.emgsamp, 1);            
            app.pWFL2=plot(app.EMGAxes_2, 1:app.emgsamp, app.emgbuf);
          
            plot(app.ScaledAxes_2, 1:app.emgsamp, app.scaledbuf2);            
            app.pD52=plot(app.D5Axes_2, 1:app.emgsamp, app.d5buf2);
           
            plot(app.TwitchAxes_2, 1:app.emgsamp, app.twitchbuf2);            
            plot(app.HLAxes_2, 1:app.emgsamp, app.hlbuf2);            
            plot(app.ASFAxes_2, 1:app.emgsamp, app.asfbuf2);           
            plot(app.ComAxes_2, 1:app.emgsamp, app.combuf2);        
            % end of second set of plots
            
%             app.SetAllXlimits(app.defaultXlimits);
%             app.setDefaultYlimits(); %need function to reset plots to default Ylim since Home
%             app.changeDefaultInteractions();
%             app.showhideAxesToolbars('off'); % since the button defaults to off

        end

        % Button pushed function: ClearMessageButton
        function ClearMessageButtonPushed(app, event)
            app.ClearMessage();
        end

        % Button pushed function: setXButton
        function setXButtonPushed(app, event)
            app.SetAllXlimits(app.defaultXlimits);
        end

        % Button pushed function: setYButton
        function setYButtonPushed(app, event)
            app.setDefaultYlimits();
        end

        % Value changed function: ToolsButton
        function ToolsButtonValueChanged(app, event)
            value = app.ToolsButton.Value; % 0 or 1
            switch value
                case 0
                    sh='off';
                    app.ToolsButton.BackgroundColor=[0.96, 0.96, 0.96];
                case 1
                    sh='on';
                    app.ToolsButton.BackgroundColor=[0.67, 0.96, 0.67];
            end
            app.showhideAxesToolbars(sh);
        end

        % Value changed function: GridsButton
        function GridsButtonValueChanged(app, event)
            value = app.GridsButton.Value;
                       switch value
                case 0
                    sh='off';
                    app.GridsButton.BackgroundColor=[0.96, 0.96, 0.96];
                case 1
                    sh='on';
                    app.GridsButton.BackgroundColor=[0.67, 0.96, 0.67];
            end
            app.showhideAxesGrids(sh); 
        end

        % Button pushed function: GetFileButton
        function GetFileButtonPushed(app, event)
            commandwindow(); % putting this before uigetfile keeps the window from getting hidden
            [fn, pn]=uigetfile('*.csv','Select Data File',app.FolderEditField.Value);
            app.DisplayFile=fullfile(pn,fn);
            app.DisplayTable=readtable(app.DisplayFile);
            app.Tlength=size(app.DisplayTable,1);
            app.DisplayFileEditField.Value=fn;
            app.FolderEditField.Value=pn;
            app.DisplayPlots();
            %drawnow;
            %figure(app.EMGDisplay); % to return focus to this window
        end

        % Button pushed function: ReadButton
        function ReadButtonPushed(app, event)
        % Read comments if available
        commandwindow(); % putting this before uigetfile keeps the window from getting hidden
        [fn, pn]=uigetfile('*.txt','Select Comment File',app.FolderEditField.Value);
        if fn~=0 % returns 0 if cancelled.
            app.CommentFileName=fullfile(pn,fn);
            app.CommentsTextArea.Value=fileread(app.CommentFileName);
            app.CommentFileNameEditField.Value=fn;
            app.FolderEditField.Value=pn;
        else
            app.TopMessage('Cancelled Comments');
        end
        end

        % Button pushed function: Sync1Button
        function Sync1ButtonPushed(app, event)
            % sync all axes to WFL1 Xaxes
            app.newXlimits=app.EMGAxes.XLim;
            app.SetAllXlimits(app.newXlimits);
        end

        % Button pushed function: Sync2Button
        function Sync2ButtonPushed(app, event)
             % sync all axes to WFL2 Xaxes
             app.newXlimits=app.EMGAxes_2.XLim;
             app.SetAllXlimits(app.newXlimits);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EMGDisplay = uifigure('Visible', 'off');
            app.EMGDisplay.Name = 'EMG Display';
            app.EMGAxes = uiaxes(app.EMGDisplay);
            title(app.EMGAxes, '')
            xlabel(app.EMGAxes, '')
            ylabel(app.EMGAxes, 'WFL')
            app.EMGAxes.XTick = [0 10 20 30 40 50];
            app.EMGAxes.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.EMGAxes.YTick = [0 50 100 150 200 250 300];
            app.EMGAxes.YTickLabel = {'0'; '50'; '100'; '150'; '200'; '250'; '300'};
            app.ScaledAxes = uiaxes(app.EMGDisplay);
            title(app.ScaledAxes, '')
            xlabel(app.ScaledAxes, '')
            ylabel(app.ScaledAxes, 'Scaled')
            app.ScaledAxes.XTick = [0 10 20 30 40 50];
            app.ScaledAxes.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.ScaledAxes.YTick = [0 50 100 150 200 250 300];
            app.ScaledAxes.YTickLabel = {'0'; '50'; '100'; '150'; '200'; '250'; '300'};
            app.TabGroup = uitabgroup(app.EMGDisplay);
            app.LogicalTab = uitab(app.TabGroup);
            app.LogicalTab.Title = 'Logical';
            app.D5Axes = uiaxes(app.LogicalTab);
            title(app.D5Axes, '')
            xlabel(app.D5Axes, '')
            ylabel(app.D5Axes, 'D5')
            app.D5Axes.XTick = [0 10 20 30 40 50];
            app.D5Axes.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.TwitchAxes = uiaxes(app.LogicalTab);
            title(app.TwitchAxes, '')
            xlabel(app.TwitchAxes, 'time (s)')
            ylabel(app.TwitchAxes, 'Twitch')
            app.TwitchAxes.XTick = [0 10 20 30 40 50];
            app.TwitchAxes.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.TwitchAxes.YTick = [-0.1 1 2 4];
            app.TwitchAxes.YTickLabel = {'0'; '1'; '2'; '4'};
            app.TwitchAxes.ZTick = [];
            app.HLAxes = uiaxes(app.LogicalTab);
            title(app.HLAxes, '')
            xlabel(app.HLAxes, 'time (s)')
            ylabel(app.HLAxes, 'HL Count')
            app.HLAxes.XTick = [0 10 20 30 40 50];
            app.HLAxes.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.HLAxes.YTick = [-0.1 5 10 15 20 25];
            app.HLAxes.YTickLabel = {'0'; '5'; '10'; '15'; '20'; '25'};
            app.HLAxes.ZTick = [];
            app.ProportionalTab = uitab(app.TabGroup);
            app.ProportionalTab.Title = 'Proportional';
            app.ASFAxes = uiaxes(app.ProportionalTab);
            title(app.ASFAxes, '')
            xlabel(app.ASFAxes, '')
            ylabel(app.ASFAxes, 'ASF')
            app.ASFAxes.XTick = [0 10 20 30 40 50];
            app.ASFAxes.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.ASFAxes.YTick = [0 50 100 150 200 250 300];
            app.ASFAxes.YTickLabel = {'0'; '50'; '100'; '150'; '200'; '250'; '300'};
            app.EMGAxes_2 = uiaxes(app.EMGDisplay);
            title(app.EMGAxes_2, '')
            xlabel(app.EMGAxes_2, '')
            ylabel(app.EMGAxes_2, 'WFL')
            app.EMGAxes_2.XTick = [0 10 20 30 40 50];
            app.EMGAxes_2.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.EMGAxes_2.YTick = [0 50 100 150 200 250 300];
            app.EMGAxes_2.YTickLabel = {'0'; '50'; '100'; '150'; '200'; '250'; '300'};
            app.ScaledAxes_2 = uiaxes(app.EMGDisplay);
            title(app.ScaledAxes_2, '')
            xlabel(app.ScaledAxes_2, '')
            ylabel(app.ScaledAxes_2, 'Scaled')
            app.ScaledAxes_2.XTick = [0 10 20 30 40 50];
            app.ScaledAxes_2.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.ScaledAxes_2.YTick = [0 50 100 150 200 250 300];
            app.ScaledAxes_2.YTickLabel = {'0'; '50'; '100'; '150'; '200'; '250'; '300'};
            app.TabGroup_2 = uitabgroup(app.EMGDisplay);
            app.LogicalTab_2 = uitab(app.TabGroup_2);
            app.LogicalTab_2.Title = 'Logical';
            app.D5Axes_2 = uiaxes(app.LogicalTab_2);
            title(app.D5Axes_2, '')
            xlabel(app.D5Axes_2, '')
            ylabel(app.D5Axes_2, 'D5')
            app.D5Axes_2.XTick = [0 10 20 30 40 50];
            app.D5Axes_2.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.TwitchAxes_2 = uiaxes(app.LogicalTab_2);
            title(app.TwitchAxes_2, '')
            xlabel(app.TwitchAxes_2, 'time (s)')
            ylabel(app.TwitchAxes_2, 'Twitch')
            app.TwitchAxes_2.XTick = [0 10 20 30 40 50];
            app.TwitchAxes_2.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.TwitchAxes_2.YTick = [-0.1 1 2 4];
            app.TwitchAxes_2.YTickLabel = {'0'; '1'; '2'; '4'};
            app.TwitchAxes_2.ZTick = [];
            app.HLAxes_2 = uiaxes(app.LogicalTab_2);
            title(app.HLAxes_2, '')
            xlabel(app.HLAxes_2, 'time (s)')
            ylabel(app.HLAxes_2, 'HL Count')
            app.HLAxes_2.XTick = [0 10 20 30 40 50];
            app.HLAxes_2.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.HLAxes_2.YTick = [-0.1 5 10 15 20 25];
            app.HLAxes_2.YTickLabel = {'0'; '5'; '10'; '15'; '20'; '25'};
            app.HLAxes_2.ZTick = [];
            app.ProportionalTab_2 = uitab(app.TabGroup_2);
            app.ProportionalTab_2.Title = 'Proportional';
            app.ASFAxes_2 = uiaxes(app.ProportionalTab_2);
            title(app.ASFAxes_2, '')
            xlabel(app.ASFAxes_2, '')
            ylabel(app.ASFAxes_2, 'ASF')
            app.ASFAxes_2.XTick = [0 10 20 30 40 50];
            app.ASFAxes_2.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.ASFAxes_2.YTick = [0 50 100 150 200 250 300];
            app.ASFAxes_2.YTickLabel = {'0'; '50'; '100'; '150'; '200'; '250'; '300'};
            app.ComAxes_2 = uiaxes(app.ProportionalTab_2);
            title(app.ComAxes_2, '')
            xlabel(app.ComAxes_2, '')
            ylabel(app.ComAxes_2, 'Command')
            app.ComAxes_2.XTick = [0 10 20 30 40 50];
            app.ComAxes_2.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.ComAxes_2.YTick = [0 50 100 150 200 250 300];
            app.ComAxes_2.YTickLabel = {'0'; '50'; '100'; '150'; '200'; '250'; '300'};
            app.PlotLimitsPanel = uipanel(app.EMGDisplay);
            app.PlotLimitsPanel.Title = 'Plot Limits';
            app.setXButton = uibutton(app.PlotLimitsPanel, 'push');
            app.setXButton.ButtonPushedFcn = createCallbackFcn(app, @setXButtonPushed, true);
            app.setXButton.Text = 'X';
            app.setYButton = uibutton(app.PlotLimitsPanel, 'push');
            app.setYButton.ButtonPushedFcn = createCallbackFcn(app, @setYButtonPushed, true);
            app.setYButton.Text = 'Y';
            app.ToolsButton = uibutton(app.PlotLimitsPanel, 'state');
            app.ToolsButton.ValueChangedFcn = createCallbackFcn(app, @ToolsButtonValueChanged, true);
            app.ToolsButton.Text = 'Tools';
            app.GridsButton = uibutton(app.PlotLimitsPanel, 'state');
            app.GridsButton.ValueChangedFcn = createCallbackFcn(app, @GridsButtonValueChanged, true);
            app.GridsButton.Text = 'Grids';
            app.Sync1Button = uibutton(app.EMGDisplay, 'push');
            app.Sync1Button.ButtonPushedFcn = createCallbackFcn(app, @Sync1ButtonPushed, true);
            app.Sync1Button.Text = 'Sync1';
            app.Sync2Button = uibutton(app.EMGDisplay, 'push');
            app.Sync2Button.ButtonPushedFcn = createCallbackFcn(app, @Sync2ButtonPushed, true);
            app.Sync2Button.Text = 'Sync2';
            app.DataPanel = uipanel(app.EMGDisplay);
            app.DataPanel.Title = 'Data';
            app.FolderEditField = uieditfield(app.DataPanel, 'text');
            app.GetFileButton = uibutton(app.DataPanel, 'push');
            app.GetFileButton.ButtonPushedFcn = createCallbackFcn(app, @GetFileButtonPushed, true);
            app.GetFileButton.Text = 'GetFile';
            app.DataLabel = uilabel(app.DataPanel);
            app.DataLabel.Text = 'Data';
            app.DisplayFileEditField = uieditfield(app.DataPanel, 'text');
            app.CommentsPanel = uipanel(app.EMGDisplay);
            app.CommentsPanel.Title = 'Comments';
            app.ReadButton = uibutton(app.CommentsPanel, 'push');
            app.ReadButton.ButtonPushedFcn = createCallbackFcn(app, @ReadButtonPushed, true);
            app.ReadButton.Text = 'Read';
            app.CommentsTextArea = uitextarea(app.CommentsPanel);
            app.CommentFileNameEditField = uieditfield(app.CommentsPanel, 'text');
            app.CommentsTextAreaLabel = uilabel(app.CommentsPanel);
            app.CommentsTextAreaLabel.Text = 'Comments';
            app.MessagesPanel = uipanel(app.EMGDisplay);
            app.MessagesPanel.Title = 'Messages';
            app.Messages = uitextarea(app.MessagesPanel);
            app.ClearMessageButton = uibutton(app.MessagesPanel, 'push');
            app.ClearMessageButton.ButtonPushedFcn = createCallbackFcn(app, @ClearMessageButtonPushed, true);
            app.ClearMessageButton.Text = 'clear';
            % Show the figure after all components are created
            app.EMGDisplay.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DisplayProcessedEMG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EMGDisplay)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EMGDisplay)
        end
    end
end