classdef DisplayXMLMarkerSet < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RemoteInfo            matlab.ui.Figure
        TranslateXMLMarkerSettoTextDisplayofNumbersLabel  matlab.ui.control.Label
        TranslateButton       matlab.ui.control.Button
        SavetoFileButton      matlab.ui.control.Button
        TranslatedDataString  matlab.ui.control.TextArea
        TranslatingLabel      matlab.ui.control.Label
        Messages              matlab.ui.control.EditField
        XMLDataString         matlab.ui.control.TextArea
        XMLStringcopiedfromnnpsfilectlvLabel  matlab.ui.control.Label
        Translatedusingbase64conversionLabel  matlab.ui.control.Label
        ClearButton           matlab.ui.control.Button
        Label                 matlab.ui.control.Label
    end

    
    properties (Access = public)
        nnp = [];
        defnodes=[1:6,9]; % default nodes to scan
        MarkerSet=[]; % string to write to display
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TranslatingLabel.Visible='off';
            app.XMLDataString.Value='';
            app.MarkerSet='';
            app.TranslatedDataString.Value='';
        end

        % Button pushed function: TranslateButton
        function TranslateButtonPushed(app, event)
            app.Messages.Value='';
            app.TranslatingLabel.Visible='on';
            app.MarkerSet=XMLMarkerSetFcn(app.XMLDataString.Value(1));
            app.TranslatedDataString.Value=app.MarkerSet;          
            app.TranslatingLabel.Visible='off';
        end

        % Button pushed function: SavetoFileButton
        function SavetoFileButtonPushed(app, event)
            % Get file name and write string to file.         
            timestring=sprintf('_%02.0f',datevec(now));
            defaultname=['MarkerSet',timestring,'.txt'];
            [fn,pn]=uiputfile('*.txt','Specify Filename and location for output text file',defaultname);
            savename=fullfile(pn,fn);
            SaveID=fopen(savename,'a'); %open in append mode in case I select the wrong file
            fprintf(SaveID,'%s',app.MarkerSet);
            fclose(SaveID);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.XMLDataString.Value='';
            app.MarkerSet='';
            app.TranslatedDataString.Value='';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RemoteInfo = uifigure('Visible', 'off');
            app.RemoteInfo.Name = 'Remote Module Information';
            app.TranslateXMLMarkerSettoTextDisplayofNumbersLabel = uilabel(app.RemoteInfo);
            app.TranslateXMLMarkerSettoTextDisplayofNumbersLabel.Text = 'Translate XML MarkerSet to Text Display of Numbers';
            app.TranslateButton = uibutton(app.RemoteInfo, 'push');
            app.TranslateButton.ButtonPushedFcn = createCallbackFcn(app, @TranslateButtonPushed, true);
            app.TranslateButton.Text = 'Translate';
            app.SavetoFileButton = uibutton(app.RemoteInfo, 'push');
            app.SavetoFileButton.ButtonPushedFcn = createCallbackFcn(app, @SavetoFileButtonPushed, true);
            app.SavetoFileButton.Text = {'Save '; 'to File'};
            app.TranslatedDataString = uitextarea(app.RemoteInfo);
            app.TranslatingLabel = uilabel(app.RemoteInfo);
            app.TranslatingLabel.Text = 'Translating ...';
            app.Messages = uieditfield(app.RemoteInfo, 'text');
            app.XMLDataString = uitextarea(app.RemoteInfo);
            app.XMLStringcopiedfromnnpsfilectlvLabel = uilabel(app.RemoteInfo);
            app.XMLStringcopiedfromnnpsfilectlvLabel.Text = 'XML String, copied from nnps file, ctl-v';
            app.Translatedusingbase64conversionLabel = uilabel(app.RemoteInfo);
            app.Translatedusingbase64conversionLabel.Text = 'Translated using base64 conversion';
            app.ClearButton = uibutton(app.RemoteInfo, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.Label = uilabel(app.RemoteInfo);
            app.Label.Text = {'In the XML file  under "GroupSet"  Copy the columns labeled groupSet_id and groupPatternMarkerSet'; 'Use Ctrl key and click at top of each column'};
            % Show the figure after all components are created
            app.RemoteInfo.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DisplayXMLMarkerSet

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RemoteInfo)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RemoteInfo)
        end
    end
end