classdef ExerciseSetup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExerciseFigure                 matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        OpenPatternsMenu               matlab.ui.container.Menu
        OpenExerciseParametersMenu     matlab.ui.container.Menu
        SaveExerciseParametersMenu     matlab.ui.container.Menu
        WriteMenu                      matlab.ui.container.Menu
        ExercisetoPMMenu               matlab.ui.container.Menu
        TimingMenu                     matlab.ui.container.Menu
        Image                          matlab.ui.control.Image
        ExerciseParametersSetupLabel   matlab.ui.control.Label
        ExerciseNumberDropDownLabel    matlab.ui.control.Label
        ExerciseNumberDropDown         matlab.ui.control.DropDown
        DescriptionEditFieldLabel      matlab.ui.control.Label
        DescriptionEditField           matlab.ui.control.EditField
        SecRepEditFieldLabel           matlab.ui.control.Label
        SecRepEditField                matlab.ui.control.NumericEditField
        RepsSetEditFieldLabel          matlab.ui.control.Label
        RepsSetEditField               matlab.ui.control.NumericEditField
        SetsRegimenEditFieldLabel      matlab.ui.control.Label
        SetsRegimenEditField           matlab.ui.control.NumericEditField
        RestminEditFieldLabel          matlab.ui.control.Label
        RestminEditField               matlab.ui.control.NumericEditField
        RegimensEditFieldLabel         matlab.ui.control.Label
        RegimensEditField              matlab.ui.control.NumericEditField
        ScriptRatemsEditFieldLabel     matlab.ui.control.Label
        ScriptRatemsEditField          matlab.ui.control.NumericEditField
        PatternsPanel                  matlab.ui.container.Panel
        TogetherDropDown               matlab.ui.control.DropDown
        TabGroup                       matlab.ui.container.TabGroup
        DirectTab                      matlab.ui.container.Tab
        PatternGroup1EditFieldLabel    matlab.ui.control.Label
        PatternGroup1EditField         matlab.ui.control.NumericEditField
        PatternGroup2EditFieldLabel    matlab.ui.control.Label
        PatternGroup2EditField         matlab.ui.control.NumericEditField
        DropDownTab                    matlab.ui.container.Tab
        PatternGroup1DropDownLabel     matlab.ui.control.Label
        PatternGroup1DropDown          matlab.ui.control.DropDown
        PatternGroup2DropDownLabel     matlab.ui.control.Label
        PatternGroup2DropDown          matlab.ui.control.DropDown
        RepTimingPanel                 matlab.ui.container.Panel
        ExtensionButton                matlab.ui.control.Button
        StartingCommandEditFieldLabel  matlab.ui.control.Label
        StartingCommandEditField       matlab.ui.control.NumericEditField
        RampUpsecEditFieldLabel        matlab.ui.control.Label
        RampUpsecEditField             matlab.ui.control.NumericEditField
        HoldUpsecEditFieldLabel        matlab.ui.control.Label
        HoldUpsecEditField             matlab.ui.control.NumericEditField
        RampDownsecEditFieldLabel      matlab.ui.control.Label
        RampDownsecEditField           matlab.ui.control.NumericEditField
        HoldDownsecEditFieldLabel      matlab.ui.control.Label
        HoldDownsecEditField           matlab.ui.control.NumericEditField
        UIAxes                         matlab.ui.control.UIAxes
        LowPowerPanel                  matlab.ui.container.Panel
        LowPowerTable                  matlab.ui.control.Table
        RegminEditFieldLabel           matlab.ui.control.Label
        MinsRegEditField               matlab.ui.control.NumericEditField
        TotalminEditFieldLabel         matlab.ui.control.Label
        TotalminEditField              matlab.ui.control.NumericEditField
        TotalhrEditFieldLabel          matlab.ui.control.Label
        TotalhrEditField               matlab.ui.control.NumericEditField
        Messages                       matlab.ui.control.TextArea
        ClearMessageButton             matlab.ui.control.Button
        FilenameEditFieldLabel         matlab.ui.control.Label
        FilenameEditField              matlab.ui.control.EditField
    end

    
    properties (Access = private)
        
    end
    
    properties (Access = public)
        LowPower=[]; % Description
        nnps=[]; %empty structure
        saveFile=[];
        Ex1F57=zeros(1,60); % parameters to be written to 1F57.  Need default values that the exercise routine can handle correctly
        ExNumber=1; %currently displayed Exercise number (1-5)
        TimingXdata=[]; % counting sequence for timing plot
        TimingYdata=[]; % output of timing sequence
        ExTable=[];
    end
    
    methods (Access = public)
        
        function updateDropDowns(app)
            groupsFile = find(cellfun(@(x) ~isempty(x), app.nnps.groups)); %finding non-empty groups
            groupsAll = groupsFile;  % removed union
            %assign names from nnps.groups
            groupNames = cell(length(groupsAll), 1);
            
            for row = 1:length(groupsAll)
                group = groupsAll(row);
                %                 if ismember(group, groupsPatterns)
                unusedStr = [];
                %                 else
                %                     unusedStr = '* ';
                %                 end
                if length(app.nnps.groups)>=group && ~isempty(app.nnps.groups{group})
                    groupNames{row} = [unusedStr num2str(group)  ' - ' app.nnps.groups{group}.name ];
                else
                    groupNames{row} = [unusedStr num2str(group)  ' - ?'];
                end
                
            end
            
            app.PatternGroup1DropDown.Items = [{'-'}; groupNames];%skip add/remove
            app.PatternGroup1DropDown.ItemsData = [0; groupsAll]; %remove the -1 and leave 0
            app.PatternGroup2DropDown.Items = [{'-'};  groupNames];%skip add/remove
            app.PatternGroup2DropDown.ItemsData = [ 0; groupsAll]; %remove the -1 and leave 0
        end
        
        function TopMessage(app,message)
            app.Messages.Value=vertcat(message, string(app.Messages.Value)); % put new message on top
        end
        
        function ClearMessages(app)
            app.Messages.Value=''; % clear messages
        end
        
        function updateDisplay(app)
            %file all the information from the parameter table
            eNum=app.ExNumber;
            ePat=app.ExTable.Ex1F57([eNum,eNum+5]);
            ebase=((eNum)*10)+1;
            ePar=app.ExTable{ebase:ebase+10,2};  % need {} to get the values from the table
            %fill in the values
            srate=app.ScriptRatemsEditField.Value/1000;
            % Low power byte
            % Bits 1-7 for nodes 1-6,9 to low power mode.  Ignore bit 8
            % LowPower is a logical table to allow for checkboxes
            app.LowPower{[1:7],1}=logical(bitget(uint8(ePar(1)),[1:7]))';  % need {} because table.  Need ' to get right orientation
            app.LowPowerTable.Data=app.LowPower;
            
            % starting command Bit 1 - (0 simulatneous or 1 alternating), Bit2 -Start(0 or 128)
            alternate=double(bitget(uint8(ePar(2)),1)); 
            app.TogetherDropDown.Value=num2str(alternate); % 0=together, 1=alternate
            app.StartingCommandEditField.Value=double(bitget(uint8(ePar(2)),2)*128);
            
            app.RampUpsecEditField.Value=ePar(3);
            app.HoldUpsecEditField.Value=ePar(4);
            app.RampDownsecEditField.Value=ePar(5);
            app.HoldDownsecEditField.Value=ePar(6);
            app.SecRepEditField.Value=sum(ePar(3:6))*srate; % to get seconds
            app.RepsSetEditField.Value=ePar(7);          
            app.SetsRegimenEditField.Value=ePar(8);
            cyclesperreg=sum(ePar(3:6))*ePar(7)*ePar(8)*(alternate+1);  % if alternating, there are twice the cycles
            app.MinsRegEditField.Value=cyclesperreg*srate/60;
            app.RegimensEditField.Value=ePar(9);
            app.RestminEditField.Value=ePar(10)/4; %15 seconds per index
            if ePar(9)>1 % more than one regimen
                app.TotalminEditField.Value=(app.MinsRegEditField.Value*app.RegimensEditField.Value)+(app.RestminEditField.Value*(ePar(9)-1));
            else
                app.TotalminEditField.Value=(app.MinsRegEditField.Value*app.RegimensEditField.Value)
            end
            app.TotalhrEditField.Value=app.TotalminEditField.Value/60;
            
            app.PatternGroup1EditField.Value=ePat(1);
            app.PatternGroup2EditField.Value=ePat(2);   
            % if I want to use the the dropdowns, I need to first load patterns into them.  Need to check the size of
            % the drop down Items array and either skip these lines or load and cell array into the Items element.
%             app.PatternGroup1DropDown.Value=ePat(1)+1; % 0 = no selection
%             app.PatternGroup2DropDown.Value=ePat(2)+1;
            
% Next I want to graph the exercise.
% to do that I need the X and Y interpolation arrays.

            xcom=zeros(1,6);
            ycom=zeros(1,6);
            sc=app.StartingCommandEditField.Value;
            ycom(1)=sc;
            ycom(6)=sc;
            ycom(2:5)=[255,255,0,0];
            if sc>0
                scstart=ePar(3)*srate/2;
                scend=scstart;
            else
                scstart=(ePar(3)*srate)-0.1;
                scend=0.1;
            end
            xcom(2)=scstart;
            for i=3:5
                xcom(i)=xcom(i-1)+(ePar(i+1)*srate);
            end
            xcom(6)=xcom(5)+scend;

            p1=plot(app.UIAxes,xcom,ycom);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.LowPower=table([false;false;false;false;false;false;false]);
            app.LowPowerTable.Data=app.LowPower;
            app.ExerciseNumberDropDown.ItemsData=1:5;
        end

        % Value changed function: TogetherDropDown
        function TogetherDropDownValueChanged(app, event)
            Value = app.TogetherDropDown.Value;
            switch Value
                case '0'
                    app.TogetherDropDown.BackgroundColor=[.9 1 .9];
                case '1'
                    app.TogetherDropDown.BackgroundColor=[1 .9 .9];
            end
            app.updateDisplay();
        end

        % Menu selected function: OpenPatternsMenu
        function OpenPatternsMenuSelected(app, event)
            [filename, filepath] = uigetfile('.mat', 'Select Patterns File'); %Patterns File
            if filename
                ld = load([filepath filename]);
                TempFile = [filepath filename];  % save filename as temporary until the file is checked
                
            else
                return;
            end
            fields = {'patterns', 'channels', 'nodes', 'groups'};
            iMissing = find(~isfield(ld, fields));
            
            str = [];
            for i=1:length(iMissing)
                str = [str ', ' fields{iMissing(i)}];
            end
            if ~isempty(str)
                warndlg(['Invalid NNP System File.  Does not contain variable(s): ' str '.'], 'Open File')
                return;
            end
            %               If the file is invalid, we don't want to update anything, including the file name
            app.saveFile = TempFile;
            
            app.nnps.groups = ld.groups; %for now, this is all I need
            app.updateDropDowns();
            
        end

        % Menu selected function: OpenExerciseParametersMenu
        function OpenExerciseParametersMenuSelected(app, event)
            [filename, filepath] = uigetfile('.mat', 'Select Exercise Parameter File'); %Patterns File
            if filename
                ld = load([filepath filename]);
                TempFile = [filepath filename];  % save filename as temporary until the file is checked
            else
                return;
            end
            fields = {'ExTable'};
            iMissing = find(~isfield(ld, fields));
            
            str = [];
            for i=1:length(iMissing)
                str = [str ', ' fields{iMissing(i)}];
            end
            if ~isempty(str)
                warndlg(['Invalid Exercise Parameter File.  Does not contain variable(s): ' str '.'], 'Open File')
                return;
            end
            %               If the file is invalid, we don't want to update anything, including the file name
            app.saveFile = TempFile;
            app.FilenameEditField.Value=app.saveFile;
            app.ExTable = ld.ExTable; %for now, this is all I need
            app.ExerciseNumberDropDown.Value=1; % dropdown uses strings by default
            app.ExNumber=1; % this is redundant
            app.TopMessage('Parameters loaded from file');
            app.updateDisplay();
        end

        % Value changed function: ExerciseNumberDropDown
        function ExerciseNumberDropDownValueChanged(app, event)
            value = app.ExerciseNumberDropDown.Value;
            app.ExNumber=value;
            app.updateDisplay();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExerciseFigure = uifigure('Visible', 'off');
            app.ExerciseFigure.Name = 'Exercise Setup';
            app.FileMenu = uimenu(app.ExerciseFigure);
            app.FileMenu.Text = 'File';
            app.OpenPatternsMenu = uimenu(app.FileMenu);
            app.OpenPatternsMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenPatternsMenuSelected, true);
            app.OpenPatternsMenu.Text = 'Open Patterns';
            app.OpenExerciseParametersMenu = uimenu(app.FileMenu);
            app.OpenExerciseParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenExerciseParametersMenuSelected, true);
            app.OpenExerciseParametersMenu.Text = 'Open Exercise Parameters';
            app.SaveExerciseParametersMenu = uimenu(app.FileMenu);
            app.SaveExerciseParametersMenu.Text = 'Save Exercise Parameters';
            app.WriteMenu = uimenu(app.ExerciseFigure);
            app.WriteMenu.Text = 'Write';
            app.ExercisetoPMMenu = uimenu(app.WriteMenu);
            app.ExercisetoPMMenu.Text = 'Exercise to PM';
            app.TimingMenu = uimenu(app.ExerciseFigure);
            app.TimingMenu.Text = 'Timing';
            app.Image = uiimage(app.ExerciseFigure);
            app.Image.ImageSource = 'Exercise Figure.png';
            app.ExerciseParametersSetupLabel = uilabel(app.ExerciseFigure);
            app.ExerciseParametersSetupLabel.Text = 'Exercise Parameters Setup';
            app.ExerciseNumberDropDownLabel = uilabel(app.ExerciseFigure);
            app.ExerciseNumberDropDownLabel.Text = 'Exercise Number';
            app.ExerciseNumberDropDown = uidropdown(app.ExerciseFigure);
            app.ExerciseNumberDropDown.Items = {'1', '2', '3', '4', '5'};
            app.ExerciseNumberDropDown.ValueChangedFcn = createCallbackFcn(app, @ExerciseNumberDropDownValueChanged, true);
            app.ExerciseNumberDropDown.Value = '1';
            app.DescriptionEditFieldLabel = uilabel(app.ExerciseFigure);
            app.DescriptionEditFieldLabel.Text = 'Description';
            app.DescriptionEditField = uieditfield(app.ExerciseFigure, 'text');
            app.SecRepEditFieldLabel = uilabel(app.ExerciseFigure);
            app.SecRepEditFieldLabel.Text = 'Sec/Rep';
            app.SecRepEditField = uieditfield(app.ExerciseFigure, 'numeric');
            app.SecRepEditField.Editable = 'off';
            app.SecRepEditField.Value = 36;
            app.RepsSetEditFieldLabel = uilabel(app.ExerciseFigure);
            app.RepsSetEditFieldLabel.Text = 'Reps/Set';
            app.RepsSetEditField = uieditfield(app.ExerciseFigure, 'numeric');
            app.RepsSetEditField.Value = 17;
            app.SetsRegimenEditFieldLabel = uilabel(app.ExerciseFigure);
            app.SetsRegimenEditFieldLabel.Text = 'Sets/Regimen';
            app.SetsRegimenEditField = uieditfield(app.ExerciseFigure, 'numeric');
            app.SetsRegimenEditField.Value = 1;
            app.RestminEditFieldLabel = uilabel(app.ExerciseFigure);
            app.RestminEditFieldLabel.Text = 'Rest, min';
            app.RestminEditField = uieditfield(app.ExerciseFigure, 'numeric');
            app.RestminEditField.Value = 1;
            app.RegimensEditFieldLabel = uilabel(app.ExerciseFigure);
            app.RegimensEditFieldLabel.Text = 'Regimens';
            app.RegimensEditField = uieditfield(app.ExerciseFigure, 'numeric');
            app.RegimensEditField.Value = 1;
            app.ScriptRatemsEditFieldLabel = uilabel(app.ExerciseFigure);
            app.ScriptRatemsEditFieldLabel.Text = 'ScriptRate, ms';
            app.ScriptRatemsEditField = uieditfield(app.ExerciseFigure, 'numeric');
            app.ScriptRatemsEditField.Value = 100;
            app.PatternsPanel = uipanel(app.ExerciseFigure);
            app.PatternsPanel.Title = 'Patterns';
            app.TogetherDropDown = uidropdown(app.PatternsPanel);
            app.TogetherDropDown.Items = {'Together', 'Alternating'};
            app.TogetherDropDown.ItemsData = {'0', '1'};
            app.TogetherDropDown.ValueChangedFcn = createCallbackFcn(app, @TogetherDropDownValueChanged, true);
            app.TogetherDropDown.Value = '0';
            app.TabGroup = uitabgroup(app.PatternsPanel);
            app.DirectTab = uitab(app.TabGroup);
            app.DirectTab.Title = 'Direct';
            app.PatternGroup1EditFieldLabel = uilabel(app.DirectTab);
            app.PatternGroup1EditFieldLabel.Text = 'Pattern Group 1';
            app.PatternGroup1EditField = uieditfield(app.DirectTab, 'numeric');
            app.PatternGroup2EditFieldLabel = uilabel(app.DirectTab);
            app.PatternGroup2EditFieldLabel.Text = 'Pattern Group 2';
            app.PatternGroup2EditField = uieditfield(app.DirectTab, 'numeric');
            app.DropDownTab = uitab(app.TabGroup);
            app.DropDownTab.Title = 'DropDown';
            app.PatternGroup1DropDownLabel = uilabel(app.DropDownTab);
            app.PatternGroup1DropDownLabel.Text = 'Pattern Group 1';
            app.PatternGroup1DropDown = uidropdown(app.DropDownTab);
            app.PatternGroup1DropDown.Items = {};
            app.PatternGroup1DropDown.Value = {};
            app.PatternGroup2DropDownLabel = uilabel(app.DropDownTab);
            app.PatternGroup2DropDownLabel.Text = 'Pattern Group 2';
            app.PatternGroup2DropDown = uidropdown(app.DropDownTab);
            app.PatternGroup2DropDown.Items = {};
            app.PatternGroup2DropDown.Value = {};
            app.RepTimingPanel = uipanel(app.ExerciseFigure);
            app.RepTimingPanel.Title = 'Rep Timing';
            app.ExtensionButton = uibutton(app.RepTimingPanel, 'push');
            app.ExtensionButton.Text = '0, Extension';
            app.StartingCommandEditFieldLabel = uilabel(app.RepTimingPanel);
            app.StartingCommandEditFieldLabel.Text = 'Starting Command';
            app.StartingCommandEditField = uieditfield(app.RepTimingPanel, 'numeric');
            app.StartingCommandEditField.Limits = [0 255];
            app.StartingCommandEditField.Editable = 'off';
            app.RampUpsecEditFieldLabel = uilabel(app.RepTimingPanel);
            app.RampUpsecEditFieldLabel.Text = 'Ramp Up,sec';
            app.RampUpsecEditField = uieditfield(app.RepTimingPanel, 'numeric');
            app.HoldUpsecEditFieldLabel = uilabel(app.RepTimingPanel);
            app.HoldUpsecEditFieldLabel.Text = 'Hold Up, sec';
            app.HoldUpsecEditField = uieditfield(app.RepTimingPanel, 'numeric');
            app.RampDownsecEditFieldLabel = uilabel(app.RepTimingPanel);
            app.RampDownsecEditFieldLabel.Text = 'Ramp Down,sec';
            app.RampDownsecEditField = uieditfield(app.RepTimingPanel, 'numeric');
            app.HoldDownsecEditFieldLabel = uilabel(app.RepTimingPanel);
            app.HoldDownsecEditFieldLabel.Text = 'Hold Down,sec';
            app.HoldDownsecEditField = uieditfield(app.RepTimingPanel, 'numeric');
            app.UIAxes = uiaxes(app.ExerciseFigure);
            title(app.UIAxes, 'Timing')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.YTick = [0 128 255];
            app.UIAxes.YTickLabel = {'0'; '128'; '255'};
            app.LowPowerPanel = uipanel(app.ExerciseFigure);
            app.LowPowerPanel.Title = 'Low Power';
            app.LowPowerTable = uitable(app.LowPowerPanel);
            app.LowPowerTable.ColumnName = '';
            app.LowPowerTable.RowName = {'1'; '2'; '3'; '4'; '5'; '6'; '9'};
            app.RegminEditFieldLabel = uilabel(app.ExerciseFigure);
            app.RegminEditFieldLabel.Text = 'Reg, min';
            app.MinsRegEditField = uieditfield(app.ExerciseFigure, 'numeric');
            app.MinsRegEditField.Editable = 'off';
            app.MinsRegEditField.Value = 1;
            app.TotalminEditFieldLabel = uilabel(app.ExerciseFigure);
            app.TotalminEditFieldLabel.Text = 'Total, min';
            app.TotalminEditField = uieditfield(app.ExerciseFigure, 'numeric');
            app.TotalminEditField.Editable = 'off';
            app.TotalminEditField.Value = 1;
            app.TotalhrEditFieldLabel = uilabel(app.ExerciseFigure);
            app.TotalhrEditFieldLabel.Text = 'Total, hr';
            app.TotalhrEditField = uieditfield(app.ExerciseFigure, 'numeric');
            app.TotalhrEditField.Editable = 'off';
            app.TotalhrEditField.Value = 1;
            app.Messages = uitextarea(app.ExerciseFigure);
            app.ClearMessageButton = uibutton(app.ExerciseFigure, 'push');
            app.ClearMessageButton.Text = 'clear';
            app.FilenameEditFieldLabel = uilabel(app.ExerciseFigure);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.ExerciseFigure, 'text');
            % Show the figure after all components are created
            app.ExerciseFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExerciseSetup

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExerciseFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExerciseFigure)
        end
    end
end