classdef PDOcfg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PDOcfgUIFigure                 matlab.ui.Figure
        ReadPMRPDOMapMatrixButton      matlab.ui.control.Button
        WritePMRPDOButton              matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        PMTab                          matlab.ui.container.Tab
        RPDOmapTable                   matlab.ui.control.Table
        x1600Label                     matlab.ui.control.Label
        RPDOsettingsTable              matlab.ui.control.Table
        x1400Label                     matlab.ui.control.Label
        StimTab                        matlab.ui.container.Tab
        NodeListStim                   matlab.ui.control.TextArea
        StimNodesLabel                 matlab.ui.control.Label
        nodetable                      matlab.ui.control.Table
        WorkinprogressnotusedyetLabel  matlab.ui.control.Label
        WritePMTDOsButton              matlab.ui.control.Button
        PMODSaveButton                 matlab.ui.control.Button
        WritePGTempAccTDOsButton       matlab.ui.control.Button
        PGTestYPDOsButton              matlab.ui.control.Button
    end

    
    properties (Access = public)
        nnp = [];
        RPDO = []; %create RPDO structure
        TPDO = []; %create TPDO structure
        myData=[]; %structure to read in from mat file
        PDOmap1600x8=[];  % separate variable if I don't need the whole structure but want to use the data later
        PGnodelist=[];
        
    end
    
    properties (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, nnp)
            if isempty(nnp)
                app.nnp = NNPAPI();
                if isempty(app.nnp.port)
                    msgbox('No port chosen for NNP Access Point')
                    delete(app.nnp)
                    return
                end
            else
                app.nnp = nnp;
            end
            app.RPDOsettingsTable.RowName={'ID','type','inhibit','compat','timer'};

        end

        % Button pushed function: ReadPMRPDOMapMatrixButton
        function ReadPMRPDOMapMatrixButtonPushed(app, event)
            %uiopen('load');  %load in the variable PDOmap1600x8, uint32 from mat file
            [fn, pn]=uigetfile('*.mat','Select PDO map file','PDOmap1600x8wfl.mat');
            app.myData= matfile(fullfile(pn,fn)) ; %associates the matfile without loading it, a structure of pointers I think
            %app.myData= matfile('PDOmap1600x8.mat') ; %associates the matfile without loading it, a structure of pointers I think
            %           app.RPDOmapTable.Data=double(app.myData.PDOmap1600x8); %read in from mat file at this point.  Use if this is only place needed
            
            app.PDOmap1600x8=app.myData.PDOmap1600x8; %reads the data from mat file into variable.  Use if I need variable elsewhere
            %           app.RPDOmapTable.Data=double(app.PDOmap1600x8); %display as decimals
            app.RPDOmapTable.Data=reshape(cellstr(dec2hex(app.PDOmap1600x8,8)),8,8)'; %display as hex, had to transpose due to cellstr function
            %            app.myData=load('PDOmap1600x8.mat') ;  %loads data into a structure of variables
            %            app.RPDOmapTable.Data=double(app.myData.PDOmap1600x8);
            
            app.RPDO.mapping = cell(8,1);
            for i=1:8
                app.RPDO.mapping{i}=app.PDOmap1600x8(i,:); %located in PDOmap1600x8.mat
            end
            
            % The following may need to change if other PDOs added or MES has different node number
            app.RPDO.id = uint32(hex2dec({'00000689','00000180','00000181','00000182','00000183','00000184','00000185','00000186'})');
            app.RPDO.type = uint8(255); %process immediately when data received
            % RPDO.type = uint8(0); %process on next SYNC
            app.RPDO.inhibit = uint16(0); %not relevant for RPDO
            app.RPDO.compat = uint8(0); %script pointer for script that runs when PDO is received
            app.RPDO.timer = uint16(0); %not relevant for RPDO
            app.RPDO.settings=cell(5,8);
            for i=1:8
                app.RPDO.settings(1,i)=cellstr(dec2hex(app.RPDO.id(i),8));
                app.RPDO.settings(2,i)=cellstr(dec2hex(app.RPDO.type,8));
                app.RPDO.settings(3,i)=cellstr(dec2hex(app.RPDO.inhibit,8));
                app.RPDO.settings(4,i)=cellstr(dec2hex(app.RPDO.compat,8));
                app.RPDO.settings(5,i)=cellstr(dec2hex(app.RPDO.timer,8));
            end
            app.RPDOsettingsTable.Data=app.RPDO.settings;
        end

        % Button pushed function: WritePMRPDOButton
        function WritePMRPDOButtonPushed(app, event)
            app.nnp.enterWaiting();  % need to make sure in waiting mode before save
            % % Write to OD 140i.n RPDO Settings
            for i=1:8
                %     for i=1:1
                
                app.nnp.write(7, ['160' num2str(i-1)], 1, app.RPDO.mapping{i}, 'uint32',8);
                app.nnp.write(7, ['140' num2str(i-1)], 1, app.RPDO.id(i), 'uint32');
                app.nnp.write(7, ['140' num2str(i-1)], 2, app.RPDO.type, 'uint8');
                app.nnp.write(7, ['140' num2str(i-1)], 3, app.RPDO.inhibit, 'uint16');
                app.nnp.write(7, ['140' num2str(i-1)], 4, app.RPDO.compat, 'uint8');
                app.nnp.write(7, ['140' num2str(i-1)], 5, app.RPDO.timer, 'uint16');
                pause(.25); % add this delay to allow serial port to catch up
            end
            
        end

        % Button pushed function: WritePMTDOsButton
        function WritePMTDOsButtonPushed(app, event)
            
            app.TPDO.mapping = cell(8,1);
            app.TPDO.mapping{1} = uint32(hex2dec({'30210108','30210208','30210308','30210408','30210508','30210608','30210708','30210808'})');
            app.TPDO.mapping{2} = uint32(hex2dec({'30210908','30210a08','30210b08','30210c08','30210d08','30210e08','30210f08','30211008'})');
            app.TPDO.mapping{3} = uint32(hex2dec({'30211108','30211208','30211308','30211408','30211508','30211608','30211708','30211808'})');
            app.TPDO.mapping{4} = uint32(hex2dec({'30211908','30211a08','30211b08','30211c08','30211d08','30211e08','30211f08','30212008'})');
            app.TPDO.mapping{5} = uint32(hex2dec({'30212108','30212208','30212308','30212408','30212508','30212608','30212708','30212808'})');
            app.TPDO.mapping{6} = uint32(hex2dec({'30212908','30212a08','30212b08','30212c08','30212d08','30212e08','30212f08','30213008'})');
            app.TPDO.mapping{7} = uint32(hex2dec({'00000000','00000000','00000000','00000000','00000000','00000000','00000000','00000000'})');
            app.TPDO.mapping{8} = uint32(hex2dec({'00000000','00000000','00000000','00000000','00000000','00000000','00000000','00000000'})');
            
            app.TPDO.id = uint32(hex2dec({'00000187','00000287','00000387','00000487','000001C7','000002C7','80000000','80000000'})');
            %     app.TPDO.type = uint8(1); %sent with every sync, regardless if data has changed
            % app.TPDO.type = uint8(0);  %sent with every sync, only if data has changed
            app.TPDO.type = uint8(253); %disabled
            app.TPDO.inhibit = uint16(0);  %not relevant for synchronous
            app.TPDO.compat = uint8(0);  %not relevant for app.TPDO
            app.TPDO.timer = uint16(0); %not relevant for synchronous
            
            %Write to OD 180i,m PM TPDOs
            for i=1:8
                app.nnp.write(7, ['1A0' num2str(i-1)], 1, app.TPDO.mapping{i}, 'uint32',8);
                app.nnp.write(7, ['180' num2str(i-1)], 1, app.TPDO.id(i), 'uint32');
                app.nnp.write(7, ['180' num2str(i-1)], 2, app.TPDO.type, 'uint8');
                app.nnp.write(7, ['180' num2str(i-1)], 3, app.TPDO.inhibit, 'uint16');
                app.nnp.write(7, ['180' num2str(i-1)], 4, app.TPDO.compat, 'uint8');
                app.nnp.write(7, ['180' num2str(i-1)], 5, app.TPDO.timer, 'uint16');
            end
        end

        % Button pushed function: PMODSaveButton
        function PMODSaveButtonPushed(app, event)
            app.nnp.saveOD(7);
        end

        % Button pushed function: WritePGTempAccTDOsButton
        function WritePGTempAccTDOsButtonPushed(app, event)
            %Write TPDOs to send back Accelerometers, temperature and current
            app.PGnodelist=str2num(cell2mat(app.NodeListStim.Value)); %at some point I want to read this from the calling function.  Assume 1-6 for now
            %Since I am writing these in different commands, I can reuse teh TPDO structure
            app.TPDO.mapping = uint32(hex2dec({'20110120','20030110','30000208','30000308','00000000','00000000','00000000','00000000'})');
            % 0x20 = 32 bits= 4 bytes for Acc data
            % 0x10 = 16 bits for temperature data
            app.TPDO.id = uint32(hex2dec('00000180'));
            % TPDO.type = uint8(1); %sent with every sync, regardless if data has changed
            % TPDO.type = uint8(0);  %sent with every sync, only if data has changed
            app.TPDO.type = uint8(253);  %disabled - will enable as needed
            app.TPDO.inhibit = uint16(0); %not relevant for synchronous
            app.TPDO.compat = uint8(0); %not relevant for RMs
            app.TPDO.timer = uint16(0); %not relevant for synchronous
            
            
            for i=1:length(app.PGnodelist)
                %BlockWrite isnt implemented at CAN level.
                for j =1:8
                    app.nnp.write(app.PGnodelist(i), '1A00', j, app.TPDO.mapping(j), 'uint32');
                end
                
                app.nnp.write(app.PGnodelist(i), '1800', 1, app.TPDO.id+app.PGnodelist(i), 'uint32');
                app.nnp.write(app.PGnodelist(i), '1800', 2, app.TPDO.type, 'uint8');
                app.nnp.write(app.PGnodelist(i), '1800', 3, app.TPDO.inhibit, 'uint16');
                app.nnp.write(app.PGnodelist(i), '1800', 4, app.TPDO.compat, 'uint8');
                app.nnp.write(app.PGnodelist(i), '1800', 5, app.TPDO.timer, 'uint16');
                
                % save to OD
                pause(.2);
                app.nnp.saveOD(app.PGnodelist(i)); % save to OD
                pause(.25);
            end
            
        end

        % Button pushed function: PGTestYPDOsButton
        function PGTestYPDOsButtonPushed(app, event)
           app.PGnodelist=str2num(cell2mat(app.NodeListStim.Value));
            app.RPDO.mapping = cell(8,1);
            app.RPDO.mapping = uint32(hex2dec({'32120110','32120210','32120310','32120410','00000000','00000000','00000000','00000000'})');
            app.RPDO.id = uint32(hex2dec({'00000187','00000287','00000387','00000487','000001C7','000002C7','00000407','00000507'})');
            app.RPDO.type=uint8(255);
            app.RPDO.inhibit=uint16(0);
            app.RPDO.compat=uint8(0);
            app.RPDO.timer=uint16(0);
            for i=1:length(app.PGnodelist)
                app.nnp.write(app.PGnodelist(i), '1400', 1, app.RPDO.id(i), 'uint32');
                app.nnp.write(app.PGnodelist(i), '1400', 2, app.RPDO.type, 'uint8');
                app.nnp.write(app.PGnodelist(i), '1400', 3, app.RPDO.inhibit, 'uint16');
                app.nnp.write(app.PGnodelist(i), '1400', 4, app.RPDO.compat, 'uint8');
                app.nnp.write(app.PGnodelist(i), '1400', 5, app.RPDO.timer, 'uint16');
                for j=1:8
                    app.nnp.write(app.PGnodelist(i),'1600',j,app.RPDO.mapping(j),'uint32');
                end
                                % save to OD
                pause(.2);
                app.nnp.saveOD(app.PGnodelist(i)); % save to OD
                pause(.25);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PDOcfgUIFigure = uifigure('Visible', 'off');
            app.PDOcfgUIFigure.Name = 'PDOcfg';
            app.ReadPMRPDOMapMatrixButton = uibutton(app.PDOcfgUIFigure, 'push');
            app.ReadPMRPDOMapMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @ReadPMRPDOMapMatrixButtonPushed, true);
            app.ReadPMRPDOMapMatrixButton.Text = {'Read PM RPDO'; 'Map Matrix'};
            app.WritePMRPDOButton = uibutton(app.PDOcfgUIFigure, 'push');
            app.WritePMRPDOButton.ButtonPushedFcn = createCallbackFcn(app, @WritePMRPDOButtonPushed, true);
            app.WritePMRPDOButton.Text = {'Write PM'; 'RPDO'};
            app.TabGroup = uitabgroup(app.PDOcfgUIFigure);
            app.PMTab = uitab(app.TabGroup);
            app.PMTab.Title = 'PM';
            app.RPDOmapTable = uitable(app.PMTab);
            app.RPDOmapTable.ColumnName = {'PDO1'; 'PDO2'; 'PDO3'; 'PDO4'; 'PDO5'; 'PDO6'; 'PDO7'; 'PDO8'};
            app.RPDOmapTable.RowName = {};
            app.x1600Label = uilabel(app.PMTab);
            app.x1600Label.Text = '0x1600';
            app.RPDOsettingsTable = uitable(app.PMTab);
            app.RPDOsettingsTable.ColumnName = {'PDO1'; 'PDO2'; 'PDO3'; 'PDO4'; 'PDO5'; 'PDO6'; 'PDO7'; 'PDO8'};
            app.RPDOsettingsTable.RowName = {};
            app.x1400Label = uilabel(app.PMTab);
            app.x1400Label.Text = '0x1400';
            app.StimTab = uitab(app.TabGroup);
            app.StimTab.Title = 'Stim';
            app.NodeListStim = uitextarea(app.StimTab);
            app.NodeListStim.Value = {'1 2 3 4 5 6'};
            app.StimNodesLabel = uilabel(app.StimTab);
            app.StimNodesLabel.Text = {'Stim'; 'Nodes'};
            app.nodetable = uitable(app.StimTab);
            app.nodetable.ColumnName = {'Column 1'; 'Column 5'; 'Column 6'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.nodetable.RowName = {};
            app.WorkinprogressnotusedyetLabel = uilabel(app.StimTab);
            app.WorkinprogressnotusedyetLabel.Text = {'Work in progress'; ' not used yet'; 'Currently displayed via'; 'ReadRestoreUserDataR3.mlapp'};
            app.WritePMTDOsButton = uibutton(app.PDOcfgUIFigure, 'push');
            app.WritePMTDOsButton.ButtonPushedFcn = createCallbackFcn(app, @WritePMTDOsButtonPushed, true);
            app.WritePMTDOsButton.Text = {'Write PM'; 'TDOs'};
            app.PMODSaveButton = uibutton(app.PDOcfgUIFigure, 'push');
            app.PMODSaveButton.ButtonPushedFcn = createCallbackFcn(app, @PMODSaveButtonPushed, true);
            app.PMODSaveButton.Text = 'PM OD Save';
            app.WritePGTempAccTDOsButton = uibutton(app.PDOcfgUIFigure, 'push');
            app.WritePGTempAccTDOsButton.ButtonPushedFcn = createCallbackFcn(app, @WritePGTempAccTDOsButtonPushed, true);
            app.WritePGTempAccTDOsButton.Text = {'Write PG'; 'Temp Acc'; 'TDOs'};
            app.PGTestYPDOsButton = uibutton(app.PDOcfgUIFigure, 'push');
            app.PGTestYPDOsButton.ButtonPushedFcn = createCallbackFcn(app, @PGTestYPDOsButtonPushed, true);
            app.PGTestYPDOsButton.Text = {'Write PG'; 'TestY'; 'PDOs'};
            % Show the figure after all components are created
            app.PDOcfgUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PDOcfg(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PDOcfgUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PDOcfgUIFigure)
        end
    end
end