classdef scriptedit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        NewMenu                      matlab.ui.container.Menu
        OpenMenu                     matlab.ui.container.Menu
        SaveMenu                     matlab.ui.container.Menu
        SaveAsMenu                   matlab.ui.container.Menu
        ExportMenu                   matlab.ui.container.Menu
        ExportLibraryMenu            matlab.ui.container.Menu
        ActiveandFlashTablesMenu     matlab.ui.container.Menu
        HelpMenu                     matlab.ui.container.Menu
        AboutMenu                    matlab.ui.container.Menu
        ToolTipsMenu                 matlab.ui.container.Menu
        EnableMenu                   matlab.ui.container.Menu
        DisableMenu                  matlab.ui.container.Menu
        ScriptingMenu                matlab.ui.container.Menu
        ReenableButtonsMenu          matlab.ui.container.Menu
        TabGroup                     matlab.ui.container.TabGroup
        ImplantTab                   matlab.ui.container.Tab
        UserIDEditField              matlab.ui.control.EditField
        AbortScriptsButton           matlab.ui.control.Button
        VerifyLabel                  matlab.ui.control.Label
        ScriptsStoredinPMFlashLabel  matlab.ui.control.Label
        ActiveScriptsLabel           matlab.ui.control.Label
        ScriptSchedulerandScriptOrderButton  matlab.ui.control.Button
        Label_3                      matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        VerifyTable                  matlab.ui.control.Table
        ScriptsEnableButton          matlab.ui.control.StateButton
        ScriptControlsPanel          matlab.ui.container.Panel
        ReadScriptfromPMButton       matlab.ui.control.Button
        DeleteScriptfromPMButton     matlab.ui.control.Button
        ClearRunOnceButton           matlab.ui.control.Button
        RunScriptTimedButton         matlab.ui.control.Button
        RunScriptOnceButton          matlab.ui.control.Button
        StopScriptButton             matlab.ui.control.Button
        StartScriptButton            matlab.ui.control.Button
        ForceIDsButton               matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        EventHistoryTextArea         matlab.ui.control.TextArea
        EventHistoryLabel            matlab.ui.control.Label
        RefreshButton                matlab.ui.control.Button
        FlashTable                   matlab.ui.control.Table
        CheckBox                     matlab.ui.control.CheckBox
        ActiveTable                  matlab.ui.control.Table
        ScriptLibraryTab             matlab.ui.container.Tab
        SetScriptOrderinSchedulerifPMDefaultsareResetLabel  matlab.ui.control.Label
        TipsButton                   matlab.ui.control.Button
        LocateFileButton             matlab.ui.control.Button
        SortSPButton                 matlab.ui.control.Button
        SortIDButton                 matlab.ui.control.Button
        ChangeRootFolderButton       matlab.ui.control.Button
        RefreshLibraryTableButton    matlab.ui.control.Button
        DownloadAllButton            matlab.ui.control.Button
        AddNewButton                 matlab.ui.control.Button
        DeleteButton                 matlab.ui.control.Button
        AddExistingButton            matlab.ui.control.Button
        EditButton                   matlab.ui.control.Button
        LibraryTable                 matlab.ui.control.Table
        AssembleDebugButton          matlab.ui.control.Button
        DownloadSelectedButton       matlab.ui.control.Button
    end

    
    properties (Access = public)
        nnp = []; % Description
        scripts = [];
        activeRow = [];
        scriptsFile = [];
        fastMode = false;
        readImages = cell(25,1);
        verifyTime = cell(25,1);
        scriptModifiedDate = {};
        strSerialPM = 'pm00000'; % to be able to use offline
        hAssembler = []; %handle to assembler/debugger window
        hScriptHelp = [];
        hScriptSched=[];
        flashValues = [];
        enableToolTips = false;
        RunTimedScript = 0;
        RefreshActiveAttempt =0;
        
        %         colors =struct('red' , [1 0 0],...
        %                       'green', [0 1 0],...
        %                       'orange', [1 .5 0],...
        %                        'yellow',  [1 1 0],...
        %                         'gray', [.96 .96 .96],...
        %                         'white', [1 1 1],...
        %                         'pink', [1 .9 .9]);
        colors =struct('red' , [1 .3 .3],...
            'green', [.4 1 .4],...
            'orange', [1 .7 .2],...
            'yellow',  [1 1 .3],...
            'gray', [.96 .96 .96],...
            'white', [1 1 1],...
            'pink', [1  .8 .8],...
            'blue', [.6 .7 1]);
        indexFirstScriptID = 596; % Index in remote flash of First Script's ID.
        % NOTE: This could vary with PM revision, if the restore list changes
        % nnp.readMemory(7,'remoteflash', app.indexFirstScriptID+(sp-1)*4, 1)
        % should return the scriptID for script #sp
        
        callingFigure=[]; %handle to calling figure
        % Code for auto file open
        %here
         userFolders=struct;  % userfiles, code, user, config, data, log
         defaultSuffix='_Scripts.mat';
         defaultName='P00_Scripts.mat';      
    end
    
    properties (Access = private)
        %column numbers for Script Table
        %row indicates script pointer (i.e. download location)
        colSel = 1;
        colID = 2;
        colName = 3;
        colFileStatus = 4;
        colOrder = 5;
        colError = 6;
        
        numCol = 6;
        
        
        %column numbers for Script Table
        %row indicates script pointer (i.e. download location)
        colFlashID = 1;
        colFlashRev = 2;
        colFlashSize = 3;
        colFlashCRC = 4;
        colFlashMatch = 5;
        
        numFlashCol = 5;
        
        
        %column numbers for Library Table
        %row may not match script ID
        colLibSel = 1;
        colLibSP = 2;
        colLibID = 3;
        colLibDL = 4;
        colLibName = 5;
        colLibRev = 6;
        colLibSize = 7;
        colLibCRC= 8;
        colLibDlDate = 9;
        colLibDlModDate = 10;
        colLibAsmDate = 11;
        colLibModDate = 12;
        colLibFile = 13;
        colLibNotes = 14;
        
        numLibCol = 14;
        
        scriptBaseAddress = hex2dec('30000');
        scriptSize = 2048;
        scriptSizeLong = 7680;
        sortMode = 'SP';
        libraryTips='';


    end
    
    methods (Access = public)
        function populateLibraryTable(app)
            %background colors are set in cellselection callback
            nScripts = length(app.scripts.library);
            
            data = cell(0,app.numLibCol);
            row=0;
            for id=1:nScripts
                
                if ~isempty(app.scripts.library{id})
                    row = row+1;
                    
                    data{row,app.colLibID}=id;
                    data{row,app.colLibSP}=app.scripts.library{id}.downloadLocation;
                    data{row,app.colLibDL}=app.scripts.library{id}.downloadable;
                    data{row,app.colLibName}=app.scripts.library{id}.name;
                    data{row,app.colLibRev}=app.scripts.library{id}.rev;
                    data{row,app.colLibCRC}=app.scripts.library{id}.crc;
                    data{row,app.colLibFile}=app.scripts.library{id}.path;
                    if isempty(app.scripts.library{id}.downloadFile)
                        data{row,app.colLibSize}=[];
                    else
                        data{row,app.colLibSize}=length(app.scripts.library{id}.downloadFile)-2; %last 2 bytes are CRC
                    end
                    
                    if ~isempty(app.scripts.library{id}.downloadFile)
                        idDownload = app.scripts.library{id}.downloadFile(end-2); %last 2 bytes are CRC
                    else
                        idDownload = [];
                    end
                    %indicate if Script ID in download doesn't match specified ID
                    if ~isempty(idDownload) && idDownload ~= id
                        data{row,app.colLibAsmDate}=['ID mismatch: ' num2str(idDownload) '!'];
                    else
                        if isdatetime(app.scripts.library{id}.assembleDate)
                            data{row,app.colLibAsmDate}=datestr(app.scripts.library{id}.assembleDate, 'yyyy-mm-dd HH:MM');
                        else
                            data{row,app.colLibAsmDate}=app.scripts.library{id}.assembleDate;
                        end
                    end
                    
                    if isfield(app.scripts.library{id}.downloadDate, app.strSerialPM)
                        if isdatetime(app.scripts.library{id}.downloadDate.(app.strSerialPM))
                            data{row,app.colLibDlDate}=datestr(app.scripts.library{id}.downloadDate.(app.strSerialPM), 'yyyy-mm-dd HH:MM');
                        else
                            data{row,app.colLibDlDate}=app.scripts.library{id}.downloadDate.(app.strSerialPM);
                        end
                    else
                        data{row,app.colLibDlDate}=[];
                    end
                    
                    app.getScriptModifiedDate(id);
                    
                    if isdatetime(app.scriptModifiedDate{id})
                        data{row,app.colLibModDate}=datestr(app.scriptModifiedDate{id}, 'yyyy-mm-dd HH:MM');
                    else
                        data{row,app.colLibModDate}=app.scriptModifiedDate{id};
                    end
                    
                    if isdatetime(app.scripts.library{id}.downloadModDate)
                        data{row,app.colLibDlModDate}=datestr(app.scripts.library{id}.downloadModDate, 'yyyy-mm-dd HH:MM');
                    else
                        data{row,app.colLibDlModDate}=app.scripts.library{id}.downloadModDate;
                    end
                    data{row,app.colLibNotes}=app.scripts.library{id}.notes;
                end
            end
            
            if isequal(app.sortMode, 'SP') && ~isempty(data)
                scriptPs = cell2mat(data(:,app.colLibSP));
                scriptPs(scriptPs == 0) = 255;
                scriptDLs = double(cell2mat(data(:,app.colLibDL)));
                scriptIDs = cell2mat(data(:,app.colLibID));
                
                [~, sortedRows] = sortrows([scriptPs, scriptDLs, scriptIDs], [1 2 3], {'ascend','descend','ascend'});
                data = data(sortedRows,:);
            end
            app.LibraryTable.Data = data;
            app.LibraryTable.ColumnFormat = {'numeric','numeric', 'numeric','','numeric','numeric','numeric','numeric','numeric','numeric','numeric','numeric','numeric'}; %right align all, but keep checkboxes
            app.refreshLibraryTableColors();
        end


        function populateFlashTable(app)
            if isempty(app.nnp)
                return
            end
            
            %requires PM 408 or newer
            data = cell(25,app.numFlashCol);
            bgColor = zeros(25, 3);
            
            flashSize = nan(25,1);
            flashID = nan(25,1);
            flashRev = nan(25,1);
            flashCRC = nan(25,1);
            flashCalcCRC = nan(25,1);
            flashCorrupt = nan(25,1);
            
            %Script Lengths
            resp = app.nnp.nmt(7, 'e0');
            if isequal(resp, hex2dec('e0'))
                resp = app.nnp.read(7, '1f58', 1, 'uint16');
                if length(resp) == 25
                    flashSize = double(resp');
                    data(:,app.colFlashSize) = mat2cell(flashSize, ones(25,1));
                else
                    data(:,app.colFlashSize) = {'?'};
                end
            else
                data(:,app.colFlashSize) = {'?'};
            end
            
            %Script IDs
            resp = app.nnp.nmt(7, 'e2');
            if isequal(resp, hex2dec('e2'))
                resp = app.nnp.read(7, '1f58', 1, 'uint16');
                if length(resp) == 25
                    flashID = double(resp');
                    data(:,app.colFlashID) = mat2cell(flashID, ones(25,1));
                else
                    data(:,app.colFlashID) = {'?'};
                end
            else
                data(:,app.colFlashID) = {'?'};
            end
            
            %Script Revs
            resp = app.nnp.nmt(7, 'e1');
            if isequal(resp, hex2dec('e1'))
                resp = app.nnp.read(7, '1f58', 1, 'uint16');
                if length(resp) == 25
                    flashRev = double(resp');
                    data(:,app.colFlashRev) = mat2cell(flashRev, ones(25,1));
                else
                    flashRev = nan(25,1);
                    data(:,app.colFlashRev) = {'?'};
                end
            else
                data(:,app.colFlashRev) = {'?'};
            end
            
            %Script CRCs
            resp = app.nnp.nmt(7, 'e3');
            if isequal(resp, hex2dec('e3'))
                resp = app.nnp.read(7, '1f58', 1, 'uint16');
                if length(resp) == 25
                    flashCRC = double(resp');
                    
                    %Script calculated CRCs
                    resp = app.nnp.nmt(7, 'e4');
                    if isequal(resp, hex2dec('e4'))
                        pause (0.03);
                        resp = app.nnp.read(7, '1f58', 1, 'uint16');
                        if length(resp) == 25
                            flashCalcCRC = double(resp');
                            for row = 1:25
                                if flashCRC(row) == flashCalcCRC(row)
                                    data{row, app.colFlashCRC} = flashCRC(row);
                                else
                                    data{row, app.colFlashCRC} = [num2str(flashCRC(row)) '~=' num2str(flashCalcCRC(row))];
                                    flashCorrupt(row) = 1;
                                end
                            end
                        else %No SDO response
                            for row = 1:25
                                data{row,app.colFlashCRC}= [num2str(flashCRC(row)) '~=?'];
                            end
                        end
                    else %No NMT_Calc_CRC response
                        for row = 1:25
                            data{row,app.colFlashCRC} = [num2str(flashCRC(row)) '~=?'];
                        end
                    end
                else %No SDO response
                    data(:,app.colFlashCRC) = {'?'};
                end
            else %No NMT_Read_CRC response
                data(:,app.colFlashCRC) = {'?'};
            end
            
            %determine color and match checkbox value
            for row=1:25
                data{row,app.colFlashMatch} = false;
                if isequal(flashCorrupt(row), 1)
                    bgColor(row,:) = app.colors.red; %red
                end
                
                scriptID = flashID(row);
                
                if isnan(scriptID)
                    bgColor(row,:) = app.colors.yellow; %yellow
                elseif scriptID == 0
                    if app.scripts.active(row)==0
                        
                        if flashSize(row)==0 %empty
                            data(row,:) = {[],[],[],[],[]};
                            bgColor(row,:) = app.colors.gray; %light gray
                        else %empty
                            bgColor(row,:) = app.colors.yellow; %yellow
                        end
                    else
                        bgColor(row,:) = app.colors.orange; %orange
                    end
                elseif length(app.scripts.library)<scriptID || isempty(app.scripts.library{scriptID}) %No matching script
                    data{row,app.colFlashMatch} = false;
                    bgColor(row,:) = app.colors.orange; %orange
                else
                    matchRev = isequal(app.scripts.library{scriptID}.rev, flashRev(row));
                    matchCRC = isequal(app.scripts.library{scriptID}.crc, flashCRC(row));
                    len = length(app.scripts.library{scriptID}.downloadFile)-2;
                    matchSize = isequal(len, flashSize(row));
                    if app.scripts.active(row) == scriptID
                        if app.ActiveTable.Data{row, app.colID}~=scriptID
                            data{row,app.colFlashMatch} = false;
                            bgColor(row,:) = app.colors.orange; %orange
                        else
                            if matchRev && matchCRC && matchSize
                                data{row,app.colFlashMatch} = true;
                                bgColor(row,:) = app.colors.green; %green
                            else
                                data{row,app.colFlashMatch} = false;
                                bgColor(row,:) = app.colors.orange; %orange
                            end
                        end
                    else
                        data{row,app.colFlashMatch} = false;
                        bgColor(row,:) = app.colors.orange; %orange
                    end
                end
            end
            
            app.flashValues.ID =flashID;
            app.flashValues.Size =flashSize;
            app.flashValues.Rev = flashRev;
            app.flashValues.CRC = flashCRC;
            app.flashValues.CalcCRC = flashCalcCRC;
            app.flashValues.Corrupt =flashCorrupt;
            
            app.FlashTable.Data = data;
            app.FlashTable.BackgroundColor = bgColor;
            app.FlashTable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', ''}; %right align
        end
        
        function populateActiveTable(app)
            if isempty(app.nnp)
                return
            end
            
            %should call populateLibraryTable before callng this
            %Can only read 50 bytes at a time so no more than 12 script controls
            resp = app.nnp.read(7, '1f51', 1,12, 'uint32'); %read script 1-12 controls
            if length(resp) ~= 12
                app.RefreshButton.BackgroundColor = app.colors.red;
                app.EventHistoryTextArea.Value = [{sprintf('Read ScriptControls 1-12: %s', app.nnp.lastError)}; app.EventHistoryTextArea.Value];
                return
            else
                scriptControl(1:12) = double(resp);
            end
            resp = app.nnp.read(7, '1f51', 13,12, 'uint32'); %read script 13-24 controls
            if length(resp) ~= 12
                app.RefreshButton.BackgroundColor = app.colors.red;
                app.EventHistoryTextArea.Value = [{sprintf('Read ScriptControls 13-24: %s', app.nnp.lastError)}; app.EventHistoryTextArea.Value];
                return
            else
                scriptControl(13:24) = double(resp);
            end
            resp =  app.nnp.read(7, '1f51', 25,1, 'uint32'); %read script 25 controls
            if length(resp) ~= 1
                app.RefreshButton.BackgroundColor = app.colors.red;
                app.EventHistoryTextArea.Value = [{sprintf('Read ScriptControls 25: %s', app.nnp.lastError)}; app.EventHistoryTextArea.Value];
                return
            else
                scriptControl(25) = double(resp);
            end
            
            refreshAgain = false;
            
            order = [];
            resp = app.nnp.read(7, '1f56', 1, 'uint8'); %read script order
            if length(resp) == 25
                order = double(resp);
            end
            app.RefreshButton.BackgroundColor = app.colors.gray;
            if isempty(app.ActiveTable.Data)
                data = cell(25,app.numCol);
            else
                data = app.ActiveTable.Data;
            end
            
            %Script Controls bit definitions:
            % bit  0   : 1=run once
            % bit  1   : 1=run continuous, 0=halt
            % bit  2   : 1 = reinit global var table
            % bit  3-7 : reserved for future use
            % bit  8-15: script ID
            % bit 16-23: reserved for future use
            % bit 24-30: error code
            % bit 31   : 1= run once successful execution
            
            %Colors:
            % green: if run continuous (run = 2)
            % blue: run once complete (run = 0, runresult = 1)
            % yellow: run once not complete running (run = 1, runresult = 0)
            % red: error
            % white: stopped
            % gray: unused
            if size(app.ActiveTable.BackgroundColor,1)<25
                bgColor = zeros(25,3);
            else
                bgColor = app.ActiveTable.BackgroundColor;
            end
            for row=1:25
                scriptID = bitshift(bitand(scriptControl(row), hex2dec('FF00')), -8);
                run = bitand(scriptControl(row), hex2dec('03'));
                errorCode = bitshift(bitand(scriptControl(row), hex2dec('7F000000')), -24);
                runOnceResult = bitget(scriptControl(row),32);
                
                
                %fprintf('\n#%1.0f run %1.0f, result %1.0f', row,run, runOnceResult);
                
                if  length(app.ActiveTable.Data)==25 &&  isequal(app.ActiveTable.Data{row,app.colSel}, true)
                    data{row,app.colSel} = true; %was already selected
                else
                    data{row,app.colSel} = false;
                end
                
                if scriptID == 0
                    bgColor(row,:) = app.colors.gray;  %gray
                    data{row,app.colError} = '  ';
                    data{row,app.colName} = '  ';
                    data{row,app.colFileStatus} = '  ';
                    data{row,app.colID} = [];
                else
                    if errorCode
                        bgColor(row,:) = app.colors.red;  %red
                        data{row,app.colError} = debugger.scripterrors{errorCode+1};
                    else
                        if run==2
                            bgColor(row,:) = app.colors.green; %green
                            data{row, app.colError} = 'Running';
                        elseif run == 0 && runOnceResult == 1
                            bgColor(row,:) = app.colors.blue;
                            if row==app.RunTimedScript
                                resp = app.nnp.read(7,'1f52', 12, 'uint32');
                                if length(resp)==1
                                    data{row, app.colError} = sprintf('Runtime: %3.2f ms', double(resp)*0.008);
                                    %app.RunTimedScript = 0;
                                else
                                    data{row, app.colError} = 'Failed to read Time-Refresh';
                                end
                            else
                                data{row, app.colError} = 'Run Once Completed';
                            end
                        elseif run == 1 && runOnceResult == 0
                            if app.RefreshActiveAttempt == 5
                                bgColor(row,:) = app.colors.yellow;
                                if row==app.RunTimedScript
                                    data{row, app.colError} = 'Run Timed Not Completed';
                                else
                                    data{row, app.colError} = 'Run Once Not Completed';
                                end
                            else
                                dotStr =  char(ones(1, app.RefreshActiveAttempt)*double('.'));
                                data{row, app.colError} =['Refreshing Status', dotStr];
                                refreshAgain = true;
                            end
                        elseif run==0
                            bgColor(row,:) = app.colors.white;  %white
                            data{row, app.colError} = '  ';
                        else
                            bgColor(row,:) = app.colors.orange;  %white
                            data{row, app.colError} = sprintf('? (ScriptStatus=0x%08X)', scriptControl(row));
                        end
                    end


                    data{row,app.colID} = scriptID;
                    
                    if length(app.scripts.library)<scriptID || isempty(app.scripts.library{scriptID})
                        data{row,app.colName} = '(ID not in Library!)';
                    else
                        if app.scripts.active(row) == scriptID
                            data{row,app.colName} = app.scripts.library{scriptID}.name;
                        else
                            data{row,app.colName} = ['(Inactive script found:' app.scripts.library{scriptID}.name ')'];
                        end
                        
                        
                        [downloadTime, dlModifyTime, assembleTime, modifyTime] = app.getTimes(scriptID);
                        if isempty(modifyTime)
                            data{row,app.colFileStatus} = 'NO SCRIPT FILE!';
                        elseif isempty(assembleTime) || assembleTime < modifyTime
                            data{row,app.colFileStatus} = 'ASSEMBLE!';
                        elseif isempty(dlModifyTime) || isempty(downloadTime) || downloadTime < dlModifyTime
                            data{row,app.colFileStatus} = 'DOWNLOAD!';
                        else
                            data{row,app.colFileStatus} = 'up to date';
                        end
                        
                    end
                end
                if isempty(order)
                    data{row,app.colOrder} = '?';
                else
                    schedule = find(order == row);
                    if ~isempty(schedule)
                        data{row,app.colOrder} = num2str(schedule);
                    else
                        data{row,app.colOrder} = '-';
                    end
                end
            end
            %first column is logical or empty, use default formatting. use char and numeric to left or right alight data
            app.ActiveTable.ColumnFormat = {'', 'numeric','char','char','char', 'char', 'char','char', 'char'};
            app.ActiveTable.Data = data;
            app.ActiveTable.BackgroundColor = bgColor;
            
            if refreshAgain
                pause(0.2);
                app.RefreshActiveAttempt = app.RefreshActiveAttempt + 1;
                app.populateActiveTable();
            else
                app.RefreshActiveAttempt = 0;
            end
            %app.populateLibraryTable();
        end
        
        function id = findNextScriptID(app)
            nScripts = length(app.scripts.library);
            
            if nScripts == 0
                id = 1;
            else
                foundEmpty = false;
                for i=1:nScripts
                    if isempty(app.scripts.library{i})
                        id = i;
                        foundEmpty = true;
                        break
                    end
                end
                if ~foundEmpty
                    id = i+1;
                    if id>255
                        id=0;
                        msgbox('No available Script IDs')
                    end
                end
            end
        end
        
        function trimScripts(app, id)
            app.scripts.library{id} =[];
            %if id is used by an active script or scripts, remove it from list
            for sp=find(app.scripts.active==id)'
                fprintf('\nRemoving ID%3.0f from active script #%3.0f\n', id, sp)
                app.scripts.active(sp)=0;
            end
            
            % if last id, remove it, not just make it empty
            while id == length( app.scripts.library)
                if isempty(app.scripts.library{id})
                    app.scripts.library(id) = [];
                    id = id - 1;
                    if id == 0
                        break;
                    end
                else
                    break;
                end
            end
        end
        
        function sp = scriptsSelected(app)
            %return as row vector so can be used in for loop
            sp = find(cellfun(@(x) ~isempty(x) && x==true, app.ActiveTable.Data(:,1)))';
            
        end
        
        function redrawScriptControls(app)
            if isempty(app.ActiveTable.Data)
                return
            end
            checkCell = app.ActiveTable.Data(:,1);
            notempty = cellfun(@(x) ~isempty(x), checkCell);
            enableCheck = any(cell2mat(checkCell(notempty)));
            enableGlobal = app.ScriptsEnableButton.Value;
            
            enableControls = enableCheck && enableGlobal;
            enableDelete = enableCheck;
            
            app.StartScriptButton.Enable=enableControls;
            app.StopScriptButton.Enable=enableControls;
            app.RunScriptOnceButton.Enable=enableControls;
            app.RunScriptTimedButton.Enable=enableControls;
            app.ClearRunOnceButton.Enable=enableControls;
            app.DeleteScriptfromPMButton.Enable=enableDelete;
            app.ReadScriptfromPMButton.Enable=enableDelete;
        end
        
        function refreshScriptStatus(app)
            scriptsEnabled = [];
            systemControl = app.nnp.read(7,'2001',1, 'uint32');
            if length(systemControl)==1
                scriptsEnabled = bitget(systemControl,5);
                runStartupScript = bitget(systemControl,9);
                alarmsEnabled = bitget(systemControl,3);
                continueOnNetworkError = bitget(systemControl,8);
                pdoEnabled1 = bitget(systemControl,4);
                pdoEnabled2 = bitget(systemControl,7);
                fprintf('\n\nSystemControl 0x%08X\n  Scripts Enabled: %1.0f\n  Run Startup Script: %1.0f\n  Alarms Enabled: %1.0f\n  Continue on Net Err: %1.0f\n',...
                    systemControl,scriptsEnabled,runStartupScript,alarmsEnabled,continueOnNetworkError);
            end
            app.redrawEnableScriptsButton(scriptsEnabled);
        end
        
        function redrawEnableScriptsButton(app, enable)
            previousValue = app.ScriptsEnableButton.Value;
            if isempty(enable)
                app.ScriptsEnableButton.BackgroundColor = app.colors.yellow;
                app.ScriptsEnableButton.Text = {'Script Enable';'Status Unknown'};
                disp('Could not read script status')
            elseif enable
                app.ScriptsEnableButton.Value = true;
                app.ScriptsEnableButton.BackgroundColor = app.colors.green;
                app.ScriptsEnableButton.Text = {'Scripts Enabled';'Push to Disable'};
            else
                app.ScriptsEnableButton.Value = false;
                app.ScriptsEnableButton.BackgroundColor = app.colors.red;
                app.ScriptsEnableButton.Text = {'Scripts Disabled';'Push to Enable'};
            end
            if previousValue ~= app.ScriptsEnableButton.Value
                app.redrawScriptControls();
            end
        end
        
        function downloadScriptByID(app, id)
            scriptIDs = cell2mat(app.LibraryTable.Data(:,app.colLibID));
            row = find(scriptIDs==id);
            
            if length(row)==1
                if ~app.LibraryTable.Data{row,app.colLibDL} || ~ismember(app.LibraryTable.Data{row,app.colLibSP}, 1:25)
                    msgbox('Selected script is not set as downloadable. Use the checkbox to enable the download and specify a unique download location (1-25)')
                else
                    app.downloadScript(row);
                end
            elseif isempty(row)
                msgbox('No script has the requested ScriptID')
            else
                msgbox('ScriptID is not unique, not downloading')
            end
        end
        
        function assembleScriptByID(app, id)
            scriptIDs = cell2mat(app.LibraryTable.Data(:,app.colLibID));
            row = find(scriptIDs==id);
            
            if length(row)==1
                app.assembleScript(row);
            elseif isempty(row)
                msgbox('No script has the requested ScriptID')
            else
                msgbox('ScriptID is not unique, not downloading')
            end
        end
        
        function enableButtons(app, enable)
            if enable
                app.redrawScriptControls(); %only enable if should be enabled based on checkboxes/script enabled
            else
                app.StartScriptButton.Enable = enable;
                app.StopScriptButton.Enable = enable;
                app.RunScriptOnceButton.Enable = enable;
                app.RunScriptTimedButton.Enable = enable;
                app.ClearRunOnceButton.Enable = enable;
                app.DeleteScriptfromPMButton.Enable = enable;
                app.ReadScriptfromPMButton.Enable = enable;
            end
            
            app.RefreshButton.Enable = enable;
            app.ScriptSchedulerandScriptOrderButton.Enable = enable;
            app.ScriptsEnableButton.Enable = enable;
            app.ForceIDsButton.Enable = enable;
            
        end
        
        function OpenFileByName(app,filename,filepath)
            if filename == 0
                return
            else
                tempfilename = fullfile(filepath, filename); % use a temporary filename until you confirm that this file works, otherwise checkDoSave fails
            end
            temp = load(tempfilename);
            if ~isfield(temp, 'active') || ~isfield(temp, 'library')
                msgbox('Scripts mat file must contain variables named "active" and "library"')
            elseif  ~isnumeric(temp.active) || ~isequal(size(temp.active), [25,1])
                msgbox('Scripts mat file must contain 25x1 vector named "active" ');
            else
                app.scripts=temp;  % if conditions are met, then copy temp over to app.script
                if ~isfield(app.scripts, 'order')
                    app.scripts.order = [];
                end
                app.scriptsFile=tempfilename;
                app.updateAllTables();
                app.LibraryTableCellSelection([]);
            end
        end
    end
    
    methods (Access = private)
        
        function refreshLibraryTableColors(app)
            
            data = app.LibraryTable.Data(:,app.colLibSel);
            if isempty(data)
                return
            end
            
            data = cell(length(data), 1);
            if ~isempty(app.activeRow)
                data{app.activeRow} = '>';
            end
            app.LibraryTable.Data(:,app.colLibSel) = data;
            
            %highlight selected row in yellow.  Non-downloadable script pointer rows are gray, assigned are white
            scriptPs = cell2mat(app.LibraryTable.Data(:,app.colLibDL));
            bgColor=zeros(length(scriptPs),3);
            for row=1:length(scriptPs)
                id = app.LibraryTable.Data{row,app.colLibID};
                [downloadTime, dlModifyTime, assembleTime, modifyTime] = app.getTimes(id);
                if scriptPs(row)==0
                    if isempty(modifyTime)
                        bgColor(row,:)=app.colors.pink; %inactive - light pink
                    else
                        bgColor(row,:)=app.colors.gray; %inactive - light gray
                    end
                else
                    
                    
                    if isempty(modifyTime)
                        bgColor(row,:)=app.colors.red; %red, no file
                    elseif isempty(assembleTime) || assembleTime < modifyTime
                        bgColor(row,:)=app.colors.yellow; %yellow
                    elseif isempty(dlModifyTime) || isempty(downloadTime) || downloadTime < dlModifyTime
                        bgColor(row,:)=app.colors.orange; %orange
                    else
                        bgColor(row,:)=app.colors.green; %green
                    end
                end
                if row == app.activeRow
                    bgColor(row,:) = bgColor(row,:)*0.7;
                end
            end
            app.LibraryTable.BackgroundColor = bgColor;
        end
        
        
        function [downloadTime, dlModifyTime, assembleTime, modifyTime] = getTimes(app, id)
            %call populateLibraryTable before running this to update script modify time
            
            if ~isempty(app.nnp) && isfield(app.scripts.library{id}.downloadDate, app.strSerialPM) && ...
                    isdatetime(app.scripts.library{id}.downloadDate.(app.strSerialPM))
                downloadTime = app.scripts.library{id}.downloadDate.(app.strSerialPM);
            else
                downloadTime = [];
            end
            
            if isdatetime(app.scripts.library{id}.downloadModDate)
                dlModifyTime = app.scripts.library{id}.downloadModDate;
            else
                dlModifyTime = [];
            end
            
            if isdatetime(app.scripts.library{id}.assembleDate)
                assembleTime = app.scripts.library{id}.assembleDate;
            else
                assembleTime = [];
            end
            
            if isdatetime(app.scriptModifiedDate{id})
                modifyTime = app.scriptModifiedDate{id};
            else
                modifyTime = [];
            end
        end
        
        function assembleScript(app, row)
            file = app.LibraryTable.Data{row,app.colLibFile};
            id = app.LibraryTable.Data{row,app.colLibID};
            scriptP = app.scripts.library{id}.downloadLocation;
            scriptName = app.scripts.library{id}.name;
            scriptRev =  app.scripts.library{id}.rev;
            if ~isempty(app.hAssembler) && isvalid(app.hAssembler) &&  isvalid(app.hAssembler.Figure)
                hFig = app.hAssembler.Figure;
                if ~isempty(app.hAssembler.DBG)
                    app.hAssembler.DBG.closeMonitors()
                end
            else
                hFig = [];
            end
            
            app.hAssembler = assembler(id, scriptP , file, scriptRev, scriptName, hFig, app);
            download = app.hAssembler.downloadImage;
            crc = app.hAssembler.scriptCRC;
            
            if isequal(app.scripts.library{id}.downloadFile, download)
                app.scripts.library{id}.assembleDate = datetime;
            else
                app.scripts.library{id}.downloadFile = download;
                app.scripts.library{id}.assembleDate = datetime;
                app.scripts.library{id}.downloadModDate = app.scripts.library{id}.assembleDate;
                if ~isempty(app.nnp)
                    app.scripts.library{id}.downloadDate.(app.strSerialPM)= 'Modified!';
                end
                app.scripts.library{id}.crc = crc;
            end
            app.updateAllTables();
        end
        
        function  downloadScript(app, row)
            %update times before downloading because *.nnpscript may have changed
            app.populateLibraryTable();
            drawnow;
            
            
            id = app.LibraryTable.Data{row,app.colLibID};
            [downloadTime, dlModifyTime, assembleTime, modifyTime] = app.getTimes(id);
            
            if isempty(assembleTime) || isempty(modifyTime) || assembleTime < modifyTime
                userResp = questdlg(['Script ID' num2str(id)  ' has been modified since last assembled. Reassemble now?']);
                if isequal(userResp, 'Yes')
                    app.assembleScript(row);
                elseif isequal(userResp, 'No')
                    return;
                end
            elseif ~isempty(downloadTime) && ~isempty(modifyTime)  && downloadTime > dlModifyTime
                userResp = questdlg(['Script ID' num2str(id)  ' has not been modified or reassembled since last download.  Download anyway?']);
                if ~isequal(userResp, 'Yes')
                    return;
                end
            end
            
            while true
                download = app.scripts.library{id}.downloadFile;
                if length(download) < 10
                    if isempty(download)
                        userResp = questdlg(['Script ID' num2str(id)  ' has not been assembled.  Assemble now?']);
                        if isequal(userResp, 'Yes')
                            app.AssembleDebugButtonPushed([]);
                            continue;
                        end
                    else
                        msgbox('invalid short download')
                    end
                    return
                else
                    break;
                end
            end
            
            
            sp = app.LibraryTable.Data{row,app.colLibSP};
            
            result = app.nnp.loadScript(sp, download);
            if isequal(result, [1 0]) %successful script download
                id = app.LibraryTable.Data{row,app.colLibID};
                app.scripts.library{id}.downloadDate.(app.strSerialPM) = datetime;
                app.scripts.active(sp)=id;
                app.updateAllTables();
                
                idRestore = app.nnp.readMemory(7,'remoteflash', app.indexFirstScriptID+(sp-1)*4, 1);
                if idRestore~=id
                    userResp = questdlg(sprintf(['The Script ID in the OD Restore File for script #%1.0f is %1.0f. '...
                        'This does not match the current ID: %1.0f.\n'...
                        'Do you want to save the current OD settings to the OD Restore File?'],...
                        sp, idRestore, id),'Script Download');
                    if isequal(userResp, 'Yes')
                        app.nnp.saveOD(7);
                        % read again to confirm change
                        idRestore = app.nnp.readMemory(7,'remoteflash', app.indexFirstScriptID+(sp-1)*4, 1);
                        if idRestore~=id
                            msgbox(sprintf(['The Script ID in the OD Restore list for script #%1.0f is %1.0f. '...
                                'This still does not match the current ID: %1.0f.\n' ...
                                'Confirm that the index of the ID of first script in the OD Restore File is at byte %1.0f .' ...
                                'If not, modify property "indexFirstScriptID" accordingly.'],...
                                sp, idRestore, id, app.indexFirstScriptID), 'Script Download');
                        end
                    end
                end
            else
                id = app.LibraryTable.Data{row,app.colLibID};
                msgbox(['error on script download: ' num2str(result)]);
                app.scripts.library{id}.downloadDate.(app.strSerialPM) = 'Error Downloading!';
                app.populateLibraryTable();
            end
            
            app.clearVerify(sp);
        end
        
        
        function updateAllTables(app)
            app.populateLibraryTable();
            if ~isempty(app.nnp) && isvalid(app.nnp)
                app.populateActiveTable();
                app.populateFlashTable();
            end
        end
        
        function clearImplantTables(app)
            app.FlashTable.Data = cell(25, app.numFlashCol);
            app.FlashTable.BackgroundColor = app.colors.gray;
            app.ActiveTable.Data = cell(25, app.numCol);
            app.ActiveTable.BackgroundColor = app.colors.gray;
            app.VerifyTable.Data = cell(25, 1);
            app.VerifyTable.BackgroundColor = app.colors.gray;
        end
        
        function clearVerify(app, sp)
            app.verifyTime{sp} = [];
            app.VerifyTable.Data{sp} = [];
            app.VerifyTable.BackgroundColor(sp,:) = app.colors.gray;
        end
        
        function getScriptModifiedDate(app, id)
            list = dir(app.scripts.library{id}.path);
            if isempty(list)
                app.scriptModifiedDate{id} = '?';
            else
                app.scriptModifiedDate{id} = datetime(list.date);
            end
        end
        
        function quit = checkDoSave(app)
            quit = false;
            if ~isempty(app.scriptsFile) %file already exists
                
                %check if any changes have been made
                savedValues = load(app.scriptsFile);
                if ~isequal(savedValues.active, app.scripts.active) || ~isequal(savedValues.library, app.scripts.library) || ~isfield(savedValues, 'order') || ~isequal(savedValues.order, app.scripts.order)
                    
                    userResp = questdlg(sprintf(['Changes detected in Script Library File. Do you want to save changes to:\n',...
                        '"%s"?\n\nHit Cancel and choose File/Save As if you want to save into a new file'], app.scriptsFile),...
                        'Script Library File');
                    if isequal(userResp, 'Yes')
                        app.SaveMenuSelected();
                    elseif isequal(userResp, 'No')
                        %nothing to do here
                    else %Cancel
                        quit = true;
                    end
                end
            elseif ~isempty(app.scripts) && ~isempty(app.scripts.library) && ~isempty(app.scripts.order) %no file exists yet, but something has been done
                userResp = questdlg('Save Script Library File?','Script Library File');
                if isequal(userResp, 'Yes')
                    app.SaveAsMenuSelected();
                elseif isequal(userResp, 'No')
                    %nothing to do here
                else %Cancel
                    quit = true;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin<2
                NNP=[];
            else
                NNP=varargin{1};
            end

            if isempty(NNP)
                app.nnp = NNPAPI();
                if isempty(app.nnp.port)
                    %msgbox('No port chosen for NNP Access Point')
                     uialert(app.UIFigure,'No port chosen for NNP Access Point','No NNP AP')
                    delete(app.nnp) 
                    app.nnp=[]; % make it empty so isempty(app.nnp) will return true
                    return
                end
                app.TabGroup.SelectedTab = app.ScriptLibraryTab;
                app.DownloadSelectedButton.Enable = 'off';
                app.DownloadAllButton.Enable = 'off';
                app.NewMenuSelected();
                
            else
                app.nnp = NNP;
                app.NewMenuSelected();
                app.refreshScriptStatus();
                app.redrawScriptControls();
% Commented out a number of lines here to all me to use this script to display and assemble scripts without test modules
% Still assumes that there is an access point connected.
%                while true
                    resp = app.nnp.getSwRev(7);
                    if length(resp)==1
                        if resp < 408
                            userResp = questdlg('This app requires PM 408 or later.  Try anyway?');
                            if ~isequal(userResp, 'Yes')
                                return
                            else
                               return
                            end
                        else
                            return
                        end
                    else
%                        userResp = questdlg('Could not get PM software rev, Retry?');
%                        if isequal(userResp, 'No')
%                            return
%                        end
                    end
%                end
                
                
  %              while true
                    resp = app.nnp.getSerial(7);
                    if length(resp)==1
                        app.strSerialPM = sprintf('pm%05d', resp);
                        %return
%                    else
%                        userResp = questdlg('Could not get PM serial number, Retry?');
%                        if isequal(userResp, 'No')
%                            %return
%                        end
                    end
%                end
            end
        
            app.LibraryTable.Data = [];
            if nargin>2
                app.callingFigure=varargin{2};
            end
            if nargin>3  % third is userFolders
                app.userFolders=varargin{3};  % userfiles, code, user, config, data, log
                app.UserIDEditField.Value=app.userFolders.code;
            end
            app.libraryTips=sprintf(['Downloads are tracked by PM serial number.\n'...
                'Note: "Script File Modified" only updates on refresh and Assemble/Download'...
                '\nColors:\n'...
                ' (Selected row is darker version of same color)\n'...
                '  green    : download up to date \n'...
                '  yellow   : script file has changed since last assembled\n'...
                '  orange   : download file has changed since last downloaded\n'...
                '  red      : cannot locate script file\n'...
                '  gray     : inactive script\n']);
            app.TipsButton.Tooltip = app.libraryTips;
            if isfield(app.userFolders,'config')&& ~isempty(app.userFolders.config)
               filename=[app.userFolders.code,app.defaultSuffix];
               app.defaultName=filename; % this is reduntant, could combine above
               filepath=app.userFolders.config;
               fullName=fullfile(filepath,filename);
               if exist(fullName,'file')==2  % make sure file exists before trying to open
                   app.OpenFileByName(filename,filepath);
               end
           end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.enableButtons(false);
            app.updateAllTables();
            app.refreshScriptStatus();
            app.StartScriptButton.BackgroundColor = app.colors.gray;
            app.StopScriptButton.BackgroundColor = app.colors.gray;
            app.RunScriptOnceButton.BackgroundColor = app.colors.gray;
            app.RunScriptTimedButton.BackgroundColor = app.colors.gray;
            app.ForceIDsButton.BackgroundColor = app.colors.gray;
            app.ClearRunOnceButton.BackgroundColor = app.colors.gray;
            app.DeleteScriptfromPMButton.BackgroundColor = app.colors.gray;
            app.ReadScriptfromPMButton.BackgroundColor = app.colors.gray;
            app.AbortScriptsButton.BackgroundColor = app.colors.gray;
            app.enableButtons(true);
        end

        % Button pushed function: AssembleDebugButton
        function AssembleDebugButtonPushed(app, event)
            if isempty(app.activeRow)
                return
            end
            app.assembleScript(app.activeRow);
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            
            checkCell = app.ActiveTable.Data(:,1);
            notempty = cellfun(@(x) ~isempty(x), checkCell);
            if all(cell2mat(checkCell(notempty)))
                app.CheckBox.Value = false;
                checkCell(notempty) = {false};
            else
                app.CheckBox.Value = true;
                checkCell(notempty) = {true};
                
            end
            app.ActiveTable.Data(:,1) = checkCell;
            app.redrawScriptControls();
        end

        % Cell edit callback: ActiveTable
        function ActiveTableCellEdit(app, event)
            col = event.Indices(2);
            row = event.Indices(1);
            newData = event.NewData;
            oldData = event.PreviousData;
            
            
            if col == 1
                %enable/disable if any/no nonempty cells are checked
                app.redrawScriptControls();
            end
            if col == 4
                if isnan(newData) || newData < 0 || newData > 25
                    app.ActiveTable.Data(row, col) = oldData;
                    return
                end
                scriptOrder = cell2mat(app.ActiveTable.Data(:,4))';
                if newData == 0
                    scriptOrder(row) = 0;
                    app.ActiveTable.Data{row, col} = 0;
                else
                    scriptOrder = cell2mat(app.ActiveTable.Data(:,4))';
                    i = find(scriptOrder==newData);
                    i(i==row) = [];
                    if ~isempty(i)
                        userResp = questdlg('Two scripts should not have same order value.  Do you want to swap order with other script, zero other script, or cancel?', 'Script Order', 'Swap', 'Zero', 'Cancel','Cancel');
                        switch userResp
                            case 'Swap'
                                scriptOrder(i) = oldData;
                                app.ActiveTable.Data{i, col} = oldData;
                            case 'Zero'
                                scriptOrder(i) = 0;
                                app.ActiveTable.Data{i, col} = 0;
                            otherwise
                                app.ActiveTable.Data{row, col} = oldData;
                                return
                        end
                    end
                end
                resp = app.nnp.write(7, '1f56', 1, scriptOrder, 'uint8'); %write script order
                if ~isequal(resp, 0)
                    msgbox('write script order may have failed, hit refresh table to read back')
                end
            end
            
            
        end

        % Cell selection callback: LibraryTable
        function LibraryTableCellSelection(app, event)
            if isempty(event)
                app.activeRow = [];
            else
                app.activeRow = event.Indices(1);
            end
            app.refreshLibraryTableColors();
            
        end

        % Button pushed function: DownloadSelectedButton
        function DownloadSelectedButtonPushed(app, event)
            if isempty(app.activeRow)
                return
            end
            
            if ~app.LibraryTable.Data{app.activeRow,app.colLibDL} || ~ismember(app.LibraryTable.Data{app.activeRow,app.colLibSP}, 1:25)
                msgbox('Selected script is not set as downloadable. Use the checkbox to enable the download and specify a unique download location (1-25)')
                return
            end
            
            app.downloadScript(app.activeRow);
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            if isempty(app.activeRow)
                return
            end
            winopen(app.LibraryTable.Data{app.activeRow,app.colLibFile})
        end

        % Button pushed function: AddExistingButton
        function AddExistingButtonPushed(app, event)
            [filename, pathname ] = uigetfile('*.nnpscript', 'Choose script(s) to add','Multiselect','on');
            
            %if single file selected, convert to cell array
            if ~iscell(filename)
                if filename == 0
                    return
                end
                filename = {filename};
            end
            nFiles = length(filename);
            
            %find next available script ID, and assign new path filename
            n=length('.nnpscript');
            for i=1:nFiles
                file = [pathname filename{i}];
                name = filename{i}(1:end-n);
                id = app.findNextScriptID();
                if id>0
                    if length(app.scripts.library)==1 && id>1 %Force column Cell array if only one entry
                        app.scripts.library = [app.scripts.library; {[]}];
                    end
                    
                    app.scripts.library{id} = struct('name', name, 'rev', 0, 'crc', [], 'path', file,...
                        'downloadLocation', 0,'downloadable', false, 'downloadFile', [],...
                        'assembleDate', [],'downloadModDate', [],'downloadDate', [],'notes', []);
                else
                    break;
                end
            end
            %update table and active row (if multiple added, active will be last one added)
            app.updateAllTables();
            scriptIDs = cell2mat(app.LibraryTable.Data(:,app.colLibID));
            if id>0
                row = find(scriptIDs==id);
                app.LibraryTableCellSelection(struct('Indices', [row, 0] ));
            else
                app.LibraryTableCellSelection([]);
            end
        end

        % Button pushed function: AddNewButton
        function AddNewButtonPushed(app, event)
            [filename, pathname ] = uiputfile('*.nnpscript', 'Create new empty script file');
            if filename == 0
                return
            else
                file = [pathname filename];
            end
            %find next available script ID, and assign new path filename
            n=length('.nnpscript');
            name = filename(1:end-n);
            id = app.findNextScriptID();
            if id>0
                %Force column Cell array
                if length(app.scripts.library)==1 && id>1
                    app.scripts.library = [app.scripts.library; {[]}];
                end
                app.scripts.library{id} = struct('name', name, 'rev', 0, 'crc', [], 'path', file,...
                    'downloadLocation', 0,'downloadable', false, 'downloadFile', [],...
                    'assembleDate', [],'downloadModDate', [],'downloadDate', [],'notes', []);
                %create file and open for editing
                fclose(fopen(file, 'w'));
                winopen(file);
                %update table and active row
                app.updateAllTables();
                scriptIDs = cell2mat(app.LibraryTable.Data(:,app.colLibID));
                row = find(scriptIDs==id);
                app.LibraryTableCellSelection(struct('Indices', [row, 0] ));
            end
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            if isempty(app.activeRow)
                return
            end
            id = app.LibraryTable.Data{app.activeRow, app.colLibID};
            app.trimScripts(id);
            app.populateLibraryTable();
            %keep row active, unless at end, and then make previous row active
            nRows = size(app.LibraryTable.Data, 1);
            if nRows>0
                if app.activeRow>nRows
                    row = nRows;
                else
                    row = app.activeRow;
                end
                app.LibraryTableCellSelection(struct('Indices', [row, 0] ));
            else
                app.LibraryTableCellSelection([]);
            end
            
            app.updateAllTables();
            
        end

        % Cell edit callback: LibraryTable
        function LibraryTableCellEdit(app, event)
            row = event.Indices(1);
            col = event.Indices(2);
            newData = event.NewData;
            oldData = event.PreviousData;
            
            switch col
                case app.colLibID %ID
                    scriptIDs = cell2mat(app.LibraryTable.Data(:,app.colLibID));
                    scriptIDs(row) = []; %ignore row being modified
                    if isnan(newData) || ismember(newData, scriptIDs) || newData<1 || newData>255
                        msgbox('script ID should be unique and in range 1-255')
                        app.LibraryTable.Data{row,col}=oldData;
                        return
                    else
                        if isempty(app.LibraryTable.Data{row,app.colLibAsmDate})
                            userResp = 'Yes';
                        else
                            userResp = questdlg('Changing the Script ID requires that you reassemble and download script.  Do you want to change the Script ID anyway?');
                        end
                        if isequal(userResp, 'Yes')
                            app.scripts.library{newData}=app.scripts.library{oldData};
                            app.trimScripts(oldData);
                        else
                            app.LibraryTable.Data{row,col}=oldData;
                            return
                        end
                    end
                case app.colLibSP %Script Pointer
                    %must be unique for downloadable scripts and 1-25 OR 0
                    scriptSPs = cell2mat(app.LibraryTable.Data(:,app.colLibSP));
                    rowIgnore = find(~cell2mat(app.LibraryTable.Data(:,app.colLibDL)));
                    scriptSPs([row; rowIgnore]) = []; %ignore row being modified and any nondownloadable scripts
                    downloadable = app.LibraryTable.Data{row,app.colLibDL};
                    if downloadable && (isnan(newData) || ismember(newData, scriptSPs) || newData<1 || newData>25)
                        msgbox('Downloadable script location should be unique and in range 1-25')
                        app.LibraryTable.Data{row,col}=oldData;
                    elseif ~downloadable && (isnan(newData) || newData<0 || newData>25)
                        msgbox('Non-downloadable script location should be in range 0-25')
                        app.LibraryTable.Data{row,col}=oldData;
                    else
                        if downloadable
                            app.scripts.active(newData)=app.LibraryTable.Data{row, app.colLibID};
                        end
                        id = app.LibraryTable.Data{row, app.colLibID};
                        app.scripts.library{id}.downloadLocation=newData;
                    end
                case app.colLibDL %Downloadable
                    scriptSPs = cell2mat(app.LibraryTable.Data(:,app.colLibSP));
                    rowIgnore = find(~cell2mat(app.LibraryTable.Data(:,app.colLibDL)));
                    scriptSPs([row; rowIgnore]) = [];
                    sp = app.LibraryTable.Data{row, app.colLibSP};
                    if newData && sp==0
                        msgbox('Script download location should be nonzero before setting to downloadable')
                        app.LibraryTable.Data{row,col}=oldData;
                    elseif newData && ismember(sp, scriptSPs)
                        msgbox('Script download location is used by another scriptID. Set that one to not downloadable first, or use a different location')
                        app.LibraryTable.Data{row,col}=oldData;
                    else
                        if newData
                            app.scripts.active(sp)=app.LibraryTable.Data{row, app.colLibID};
                        end
                        id = app.LibraryTable.Data{row, app.colLibID};
                        app.scripts.library{id}.downloadable = newData;
                    end
                case app.colLibName %name
                    id = app.LibraryTable.Data{row, app.colLibID};
                    app.scripts.library{id}.name = newData;
                case app.colLibRev %Revision
                    id = app.LibraryTable.Data{row, app.colLibID};
                    if ~isnumeric(newData) || isnan(newData) || ~isreal(newData) || newData<0 || newData>65535
                        msgbox('Script Rev (Verison Number) should be integer between 0 and 65535')
                        app.LibraryTable.Data{row,col}=oldData;
                    else
                        app.scripts.library{id}.rev = newData;
                    end
                case app.colLibFile %file path
                    id = app.LibraryTable.Data{row, app.colLibID};
                    app.scripts.library{id}.path = newData;
                case app.colLibNotes %notes
                    id = app.LibraryTable.Data{row, app.colLibID};
                    app.scripts.library{id}.notes = newData;
                otherwise
                    %not modifiable
                    return
            end
            
            %all changes to this point require table updates
            app.updateAllTables();
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
          
            if app.checkDoSave()
                return
            end
            
            if isfield(app.userFolders,'config')&& ~isempty(app.userFolders.config)
                    fname=[app.userFolders.code,app.defaultSuffix];
                    defName=fullfile(app.userFolders.config,fname); % name with path                  
            else
                defName=app.defaultName; % name without path
            end
			[filename, filepath] = uigetfile('*.mat', 'Open script parameters mat File',defName); % Exercise parameter file

            if filename
                app.OpenFileByName(filename,filepath);
            end

            %{
            if filename == 0
                return
            else
                tempfilename = [pathname filename]; % use a temporary filename until you confirm that this file works, otherwise checkDoSave fails
            end
            temp = load(tempfilename);
            if ~isfield(temp, 'active') || ~isfield(temp, 'library')
                msgbox('Scripts mat file must contain variables named "active" and "library"')
            elseif  ~isnumeric(temp.active) || ~isequal(size(temp.active), [25,1])
                msgbox('Scripts mat file must contain 25x1 vector named "active" ');
            else
                app.scripts=temp;  % if conditions are met, then copy temp over to app.script
                if ~isfield(app.scripts, 'order')
                    app.scripts.order = [];
                end
                app.scriptsFile=tempfilename;
                app.updateAllTables();
                app.LibraryTableCellSelection([]);
            end
%}
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            if isempty(app.scriptsFile)
                SaveAsMenuSelected(app, event);
            else
                active = app.scripts.active;
                library = app.scripts.library;
                order = app.scripts.order;
                save(app.scriptsFile, 'active', 'library', 'order');
            end
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
            defName=fullfile(app.userFolders.config,app.defaultName);
            [filename, pathname ] = uiputfile('*.mat', 'Save As script parameters mat File',defName);
            if filename == 0
                return
            else
                app.scriptsFile = fullfile(pathname, filename);
                SaveMenuSelected(app, event);
            end
        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
            if app.checkDoSave()
                return
            end
            app.scriptsFile = [];
            app.scripts.library = cell(0,1);
            app.scripts.active = zeros(25,1);
            app.scripts.order = [];

            app.clearImplantTables();
            app.populateLibraryTable();
            app.LibraryTableCellSelection([]);
            app.activeRow = [];
        end

        % Button pushed function: ForceIDsButton
        function ForceIDsButtonPushed(app, event)
            app.enableButtons(false);
            drawnow
            for i = 1:25
                id = app.FlashTable.Data{i,1};
                if isscalar(id) && isnumeric(id)
                    %TODO: currently only pushes non zero IDs.  Should it also push zeros?
                    id = bitshift(uint32(id),8);
                    resp = app.nnp.write(7, '1f51', i, id, 'uint32');
                    if ~isequal(resp, 0)
                        disp('failed write - should retry')
                    end
                end
            end

            app.updateAllTables();
            
            userResp = questdlg('Do you want to save the IDs in the Object Dictionary?');
            if isequal(userResp, 'Yes')
                app.nnp.nmt(7, '0a');
            end
            app.enableButtons(true);
        end

        % Button pushed function: DeleteScriptfromPMButton
        function DeleteScriptfromPMButtonPushed(app, event)
            app.enableButtons(false);
            drawnow
            for sp=app.scriptsSelected
                resp = app.nnp.nmt(7, '90', sp); %delete script NMT
                if ~isequal(resp, hex2dec('90'))
                    app.DeleteButton.BackgroundColor = app.colors.red;
                    app.EventHistoryTextArea.Value = [{sprintf('DeleteScript #%u: %s', sp, app.nnp.lastError)}; app.EventHistoryTextArea.Value];
                    break
                else
                    app.DeleteButton.BackgroundColor = app.colors.gray;
                end
                app.clearVerify(sp);
            end
            %JML TODO: should indicate in Library that script has been deleted?
            
            app.updateAllTables();
            app.enableButtons(true);

        end

        % Button pushed function: StartScriptButton
        function StartScriptButtonPushed(app, event)
            app.enableButtons(false);
            drawnow
            for sp = app.scriptsSelected
                resp = app.nnp.nmt(7,'8D', sp); %Start Script
                if isequal(resp, hex2dec('8D'))
                    app.ActiveTable.Data{sp, app.colError} = 'Started';
                    if ~isequal(app.ActiveTable.BackgroundColor(sp, :), app.colors.gray)
                        app.ActiveTable.BackgroundColor(sp, :) = app.colors.white;
                    end
                    app.StartScriptButton.BackgroundColor = app.colors.gray;
                else
                    app.StartScriptButton.BackgroundColor = app.colors.red;
                    app.EventHistoryTextArea.Value = [{sprintf('StartScript #%u: %s', sp, app.nnp.lastError)}; app.EventHistoryTextArea.Value];
                    break
                end
                drawnow
            end
            app.populateActiveTable();
            app.enableButtons(true);
        end

        % Button pushed function: StopScriptButton
        function StopScriptButtonPushed(app, event)
            app.enableButtons(false);
            drawnow
            for sp = app.scriptsSelected
                resp = app.nnp.nmt(7,'AE', sp); %Stop Script
                if isequal(resp, hex2dec('AE'))
                    app.ActiveTable.Data{sp, app.colError} = 'Stopped';
                    if ~isequal(app.ActiveTable.BackgroundColor(sp, :), app.colors.gray)
                        app.ActiveTable.BackgroundColor(sp, :) = app.colors.white;
                    end
                    app.StopScriptButton.BackgroundColor = app.colors.gray;
                else
                    app.StopScriptButton.BackgroundColor = app.colors.red;
                    app.EventHistoryTextArea.Value = [{sprintf('StopScript #%u: %s', sp, app.nnp.lastError)}; app.EventHistoryTextArea.Value];
                    break
                end
                drawnow
            end
            app.populateActiveTable();
            app.enableButtons(true);
        end

        % Button pushed function: RunScriptOnceButton
        function RunScriptOnceButtonPushed(app, event)
            app.enableButtons(false);
            drawnow
            for sp = app.scriptsSelected
                resp = app.nnp.nmt(7,'B3', sp); %Run Script Once
                if isequal(resp, hex2dec('B3'))
                    app.ActiveTable.Data{sp, app.colError} = 'Run Once';
                    if ~isequal(app.ActiveTable.BackgroundColor(sp, :), app.colors.gray)
                        app.ActiveTable.BackgroundColor(sp, :) = app.colors.white;
                    end
                    app.RunScriptOnceButton.BackgroundColor = app.colors.gray;
                else
                    app.RunScriptOnceButton.BackgroundColor = app.colors.red;
                    app.EventHistoryTextArea.Value = [{sprintf('RunOnce #%u: %s', sp, app.nnp.lastError)}; app.EventHistoryTextArea.Value];
                    break
                end
                drawnow
            end
            app.populateActiveTable();
            app.enableButtons(true);
        end

        % Button pushed function: ClearRunOnceButton
        function ClearRunOnceButtonPushed(app, event)
            app.enableButtons(false);
            drawnow
            for sp = app.scriptsSelected
                resp = app.nnp.read(7, '1f51', sp, 'uint32');
                if length(resp)==1
                    val = bitset(resp, 1, 0); %clear run once bit
                    val = bitset(val, 32, 0); %clear run once result bit
                    resp = app.nnp.write(7, '1f51', sp, val, 'uint32');
                    if isequal(resp, 0)
                        app.ActiveTable.Data{sp, app.colError} = '   ';
                        if ~isequal(app.ActiveTable.BackgroundColor(sp, :), app.colors.gray)
                            app.ActiveTable.BackgroundColor(sp, :) = app.colors.white;
                        end
                        app.ClearRunOnceButton.BackgroundColor = app.colors.gray;
                    else
                        app.ClearRunOnceButton.BackgroundColor = app.colors.red;
                        app.EventHistoryTextArea.Value = [{sprintf('ClearRunOnce Read #%u: %s', sp, app.nnp.lastError)}; app.EventHistoryTextArea.Value];
                        break
                    end
                else
                    app.ClearRunOnceButton.BackgroundColor = app.colors.red;
                    app.EventHistoryTextArea.Value = [{sprintf('ClearRunOnce Write #%u: %s', sp, app.nnp.lastError)}; app.EventHistoryTextArea.Value];
                    break
                end
                drawnow
            end
            app.populateActiveTable();
            app.enableButtons(true);
        end

        % Button pushed function: RunScriptTimedButton
        function RunScriptTimedButtonPushed(app, event)
            app.enableButtons(false);
            drawnow
            sp = app.scriptsSelected;
            if length(sp)>1
                msgbox('Can only time one script at a time')
            else
                resp = app.nnp.nmt(7,'B3', sp, 1); %Run Script Once, Timed
                if isequal(resp, hex2dec('B3'))
                    app.ActiveTable.Data{sp, app.colError} = 'Run Timed';
                    if ~isequal(app.ActiveTable.BackgroundColor(sp, :), app.colors.gray)
                        app.ActiveTable.BackgroundColor(sp, :) = app.colors.white;
                    end
                    app.RunTimedScript = sp;
                    app.RunScriptTimedButton.BackgroundColor = app.colors.gray;
                else
                    app.RunScriptTimedButton.BackgroundColor = app.colors.red;
                    app.EventHistoryTextArea.Value = [{sprintf('RunTimed #%u: %s',sp, app.nnp.lastError)}; app.EventHistoryTextArea.Value];
                end
            end
            drawnow
            app.populateActiveTable();
            app.enableButtons(true);
        end

        % Menu selected function: EnableMenu
        function EnableMenuSelected(app, event)
            app.enableToolTips = true;
            app.ActiveTable.Tooltip = sprintf(['Reads from:\n  0x1F51 (Script Controls)\n  0x1F56 (Script Order)\n'...
                'and references scripts from Library that have matching script IDs.\n\n'...
                'Columns marked with * (name and file status) are referenced from library, and do not necessarily\n ' ...
                'reflect what is currently downloaded in module.\n\n'...
                'See Stored Scripts Table to see if stored scripts match library scripts!\n\n'...
                'Use checkboxes to control which scripts are affected by the Script Control buttons.\n\n'...
                'Change Script Order using the Script Scheduler.\n\n'...
                'To change script IDs:\n'...
                '  use the Script Library tab and download scripts\n'...
                '     OR\n'...
                '   force scriptIDs to match values from script flash.\n'...
                '\nColors:\n'...
                '  green : script running\n'...
                '  blue  : run once/timed completed without error\n'...
                '  yellow: run once/timed not completed\n'...
                '  red   : script errored\n'...
                '  white : script not running\n'...
                '  gray  : script unused\n']);
            
            app.FlashTable.Tooltip = sprintf(['Reads script data directly from PM script storage (Flash).\n'...
                '\nThe checkbox indicates that ID/Rev/Size/CRC all match the Library script targeted to that location (#)\n'...
                'Click on the unchecked boxes to compare the Library and Stored script data.\n'...
                '\nColors:\n'...
                '  green    : Match (also checked)\n'...
                '  red      : Recalculated CRC from Stored Script does not match Stored CRC (Flash corruption)\n'...
                '  gray     : No script at location\n'...
                '  orange   : Mismatch\n'...
                '  yellow   : No script at location (but Library has an active script)']);
            
            app.VerifyTable.Tooltip = sprintf('Shows results of full verify.  Press "Read Script from PM" to update the verify for the selected scripts');
            
            %LibraryTableTab tooltip looks silly because it doesn't fit in window, so use tooltip on tab instead
%             app.TipsButton.Tooltip = sprintf(['Downloads are tracked by PM serial number.\n'...
%                 'Note: "Script File Modified" only updates on refresh and Assemble/Download'...
%                 '\nColors:\n'...
%                 ' (Selected row is darker version of same color)\n'...
%                 '  green    : download up to date \n'...
%                 '  yellow   : script file has changed since last assembled\n'...
%                 '  orange   : download file has changed since last downloaded\n'...
%                 '  red      : cannot locate script file\n'...
%                 '  gray     : inactive script\n']);
            
            app.AssembleDebugButton.Tooltip = sprintf('Set revision before assembly\n to include rev number in download');
            
        end

        % Menu selected function: DisableMenu
        function DisableMenuSelected(app, event)
            app.enableToolTips = false;
            app.ActiveTable.Tooltip = '';
            app.FlashTable.Tooltip = '';
            app.ScriptLibraryTab.Tooltip = '';
%             app.TipsButton.Tooltip = '';
            app.VerifyTable.Tooltip = '';
            app.AssembleDebugButton.Tooltip ='';
        end

        % Value changed function: ScriptsEnableButton
        function ScriptsEnabledButtonValueChanged(app, event)
            if app.ScriptsEnableButton.Value
                resp = app.nnp.nmt(7, '8e'); %enable scripts
                if ~isequal(resp, hex2dec('8e'))
                    app.refreshScriptStatus(); %no confirmation on NMT, so refresh (calls redraw button)
                else
                    app.redrawEnableScriptsButton(true);
                end
            else
                resp = app.nnp.nmt(7, '8f'); %disable scripts
                if ~isequal(resp, hex2dec('8f'))
                    app.refreshScriptStatus(); %no confirmation on NMT, so refresh (calls redraw button)
                else
                    app.redrawEnableScriptsButton(false);
                end
                app.populateActiveTable(); %disable clears runbits and errors, so refresh table

            end
            
            app.redrawScriptControls();
        end

        % Button pushed function: DownloadAllButton
        function DownloadAllButtonPushed(app, event)
            %download in order of download location (sp)
            for sp=1:25
                
                dlTable = cell2mat(app.LibraryTable.Data(:,app.colLibDL));
                spTable = cell2mat(app.LibraryTable.Data(:,app.colLibSP));
                
                row = find(spTable==sp & dlTable);
                
                if length(row)==1
                    disp(['downloading #' num2str(sp) , 'row: ', num2str(row)])
                    app.downloadScript(row);
                end
            end

        end

        % Button pushed function: ReadScriptfromPMButton
        function ReadScriptfromPMButtonPushed(app, event)
            for sp = app.scriptsSelected()
                address = app.scriptBaseAddress + (sp-1)*app.scriptSize;
                if sp==25
                    len = app.scriptSizeLong;
                else
                    len = app.scriptSize;
                end
                print = true;
                app.readImages{sp} = app.nnp.readMemory(7, 'flash', address, len, print, app.fastMode, print)';
                
                if size(app.VerifyTable.BackgroundColor,1)<25
                    bgColor = repmat(app.colors.gray, 25, 1);
                else
                    bgColor = app.VerifyTable.BackgroundColor();
                end
                if isempty(app.readImages{sp})
                    app.FlashTable.Data{sp,app.colFlashVerify} = 'Read Fail';
                else
                    id = app.flashValues.ID(sp);
                    if isempty(id) || isnan(id)
                        id = 0;
                    end
                    if id>0 && length(app.scripts.library) >= id && ~isempty(app.scripts.library{id})
                        len = length(app.scripts.library{id}.downloadFile);
                        if isequal(app.scripts.library{id}.downloadFile, app.readImages{sp}(1:len))
                            app.VerifyTable.Data{sp} = true;
                            bgColor(sp,:) = app.colors.green; %green
                        else
                            app.VerifyTable.Data{sp} = false;
                            bgColor(sp,:) = app.colors.red; %red
                        end
                    elseif all(app.readImages{sp}==255)
                        app.VerifyTable.Data{sp} = true;
                        bgColor(sp,:) = [0.96 0.96 0.96]; %gray
                    else
                        app.VerifyTable.Data{sp} = false;
                        bgColor(sp,:) = app.colors.red; %red
                    end
                    app.VerifyTable.BackgroundColor = bgColor;
                    app.verifyTime{sp} = datetime;
                end
            end
        end

        % Callback function
        function TriggeredScriptsButtonPushed(app, event)
            app.scripttrigger(app.nnp);
        end

        % Button pushed function: RefreshLibraryTableButton
        function RefreshLibraryTableButtonPushed(app, event)
            app.populateLibraryTable();
        end

        % Button pushed function: ChangeRootFolderButton
        function ChangeRootFolderButtonPushed(app, event)
            k=1;
            paths = {}; % path including all directories and filename
            for i=1:length(app.scripts.library)
                if ~isempty(app.scripts.library{i})
                    paths{k} = app.scripts.library{i}.path;
                    k=k+1;
                end
            end
            % the theory here is that you have copied a directory of script and/or user files from one computer to another, but
            % they may have a different base directory.  So this is changing that base directory by looking for the
            % common path and replacing it with the new path.
            commonPath = paths{1};
            notCommonPath = true;
            while notCommonPath
                endPath = find(commonPath=='\', 1, 'last');
                if isempty(endPath)
                    msgbox('No common path found.  Paths will need to be changed individually')
                    return;
                end
                notCommonPath = false;
                commonPath = commonPath(1:endPath-1);
                for k =2:length(paths)
                    if ~startsWith(paths{k}, commonPath)
                        notCommonPath = true;
                        break;
                    end
                end
            end
            
            newPath = uigetdir(cd ,sprintf('Found common path: "%s". Choose replacement path:', commonPath));
            if newPath
                for i=1:length(app.scripts.library)
                    if ~isempty(app.scripts.library{i})
                        app.scripts.library{i}.path = strrep(app.scripts.library{i}.path, commonPath, newPath);
                    end
                end
            end
            app.populateLibraryTable();
        end

        % Menu selected function: ScriptingMenu
        function ScriptingMenuSelected(app, event)
            if isempty(app.hScriptHelp) || ~isvalid(app.hScriptHelp)
                app.hScriptHelp =scripthelp();
            else
                %flash visibility to bring to top
                app.hScriptHelp.UIFigure.Visible = 'off';
                app.hScriptHelp.UIFigure.Visible = 'on';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.checkDoSave()
                return
            end
            if ~isempty(app.hScriptHelp) && isvalid(app.hScriptHelp)
                delete(app.hScriptHelp)
            end
            if ~isempty(app.hScriptSched) && isvalid(app.hScriptSched)
                delete(app.hScriptSched)
            end
            if ~isempty(app.hAssembler) && isvalid(app.hAssembler) && isvalid(app.hAssembler.Figure)
                close(app.hAssembler.Figure)
            end
            if ishandle(app.callingFigure) % check that is is valid
                figure(app.callingFigure); % bring to front
            end

            delete(app)
            
        end

        % Cell edit callback: FlashTable
        function FlashTableCellEdit(app, event)
            
            oldData = event.PreviousData;
            
            row = event.Indices(1);
            col = event.Indices(2);
            
            if col == app.colFlashMatch
                app.FlashTable.Data{row, col} = oldData;
                % change checkbox back (ideally checkbox wouldn't change value, but this method
                % works better than using the cell selection callback.  We are essentially using
                % the checkbox as a button
                
                if ~oldData %Only show compare information if not a match
                    flashSP = row;
                    flashID = app.flashValues.ID(row);
                    
                    scriptSPs = cell2mat(app.LibraryTable.Data(:,app.colLibSP));
                    scriptDLs = cell2mat(app.LibraryTable.Data(:,app.colLibDL));
                    scriptIDs = cell2mat(app.LibraryTable.Data(:,app.colLibID));
                    rowLib = find(scriptSPs == flashSP & scriptDLs == true);
                    
                    activeID =  app.ActiveTable.Data{row,app.colID};
                    
                    if length(rowLib)==1
                        scriptID = scriptIDs(rowLib);
                        msgboxStr = [];
                        if isequal(app.flashValues.Corrupt(row), false)
                            msgboxStr = sprintf('Script Flash is corrupted!\n\n');
                        end
                        if ~isequal(activeID, flashID)
                            msgboxStr = sprintf('Active ID does not match ID from Flash!\nConsider forcing IDs from Flash to Actve Table or manually changing PM OD 1F51\n\n');
                        end
                        msgboxStr = [msgboxStr, '\fontname{Courier New}\fontsize{12}',...
                            sprintf(['Script Location # %d\n\n',...
                            '        File   Implant\n',...
                            'ID  : %6d    %6d (%3d)\n',...
                            'Size: %6d    %6d\n',...
                            'Rev : %6d    %6d\n',...
                            'CRC : %6d    %6d (%5d)'],...
                            flashSP,...
                            scriptID, flashID, activeID,...
                            app.LibraryTable.Data{rowLib,app.colLibSize}, app.flashValues.Size(row),...
                            app.LibraryTable.Data{rowLib,app.colLibRev}, app.flashValues.Rev(row),...
                            app.LibraryTable.Data{rowLib,app.colLibCRC}, app.flashValues.CRC(row), app.flashValues.CalcCRC(row))];
                        msgboxStyle.Interpreter = 'tex';
                        msgboxStyle.WindowStyle = 'modal';
                        msgbox(msgboxStr, 'Compare', msgboxStyle);
                        
                    elseif length(rowLib)>1
                        msgbox(sprintf('Error: Multiple downloadable scripts in Library Table share script location #% d!', flashSP));
                    else
                        rowLib = find(scriptSPs == flashID);
                        if length(rowLib)>=1 %script location found in Library, but not set to downloadable
                            msgbox(sprintf('Script in Library Table at script location # %d is not set to downloadable!', flashSP));
                        else %script location not found in Library
                            if find(scriptIDs == flashID) %script ID found in Library
                                msgbox(sprintf('No script in Library Table at script location # %d, but Script ID: %d was found!', flashSP, flashID));
                            else %script ID not found in Library
                                msgbox(sprintf('No script in Library Table at script location # %d or with Script ID: %d!', flashSP, flashID));
                            end
                        end
                    end
                    
                    
                end
            end
        end

        % Cell edit callback: VerifyTable
        function VerifyTableCellEdit(app, event)
            %change checkbox back
            oldData = event.PreviousData;
            row = event.Indices(1);
            app.VerifyTable.Data{row} = oldData;
            msgbox('not yet implmented: will compare uploaded file to library download file')
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            if app.TabGroup.SelectedTab == app.ImplantTab && isempty(app.nnp)
                app.TabGroup.SelectedTab = event.OldValue;
                msgbox('Implant Tab is not available if NNP was not passed as an argument')
            end
        end

        % Button pushed function: SortIDButton
        function SortIDButtonPushed(app, event)
            if ~isequal(app.sortMode, 'ID')
                app.sortMode = 'ID';
                app.populateLibraryTable;
            end
        end

        % Button pushed function: SortSPButton
        function SortSPButtonPushed(app, event)
            if ~isequal(app.sortMode, 'SP')
                app.sortMode = 'SP';
                app.populateLibraryTable;
            end
        end

        % Button pushed function: ScriptSchedulerandScriptOrderButton
        function ScriptSchedulerandScriptOrderButtonPushed(app, event)
            if isempty(app.hScriptSched) || ~isvalid(app.hScriptSched)
                app.hScriptSched = scriptsched(app);
            else
                %flash visibility to bring to top
                app.hScriptSched.UIFigure.Visible = 'off';
                app.hScriptSched.UIFigure.Visible = 'on';
            end
        end

        % Button pushed function: AbortScriptsButton
        function AbortScriptsButtonPushed(app, event)
            resp = app.nnp.nmt(7,'B5'); %Abort all Scripts
            if isequal(resp, hex2dec('B5'))
                app.AbortScriptsButton.BackgroundColor = app.colors.gray;
            else
                app.AbortScriptsButton.BackgroundColor = app.colors.red;
            end
            app.populateActiveTable();
        end

        % Menu selected function: ReenableButtonsMenu
        function ReenableButtonsMenuSelected(app, event)
            app.enableButtons(true);
        end

        % Menu selected function: ExportLibraryMenu
        function ExportLibraryMenuSelected(app, event)
           %here
           % export library table as xlsx file
            [pn, fn, ~]=fileparts(app.scriptsFile);
            newname=fullfile(pn,strcat(fn,'_library.xlsx'));
            [fn, pn]=uiputfile('*.xlsx','Name to save Library Table',newname);
            newfile=fullfile(pn,fn);
            outtable=cell2table(app.LibraryTable.Data);
            outtable.Properties.VariableNames=app.LibraryTable.ColumnName;
            %write the file
            writetable(outtable,newfile);
        end

        % Menu selected function: ActiveandFlashTablesMenu
        function ActiveandFlashTablesMenuSelected(app, event)
             %here
            % Create new table that is concatenation of Active and Flash
            % each table has an ID column, so I can't just copy the columnnames,because they would not be unique.
            newAF=cell2table([app.ActiveTable.Data,app.FlashTable.Data]); % new table
            AFcol={'ck','aID','Name','FileStatus','Sched','RunStatus','sID','Rev','Size','CRC','Equal'};
            newAF.Properties.VariableNames=AFcol;
            [pn, fn, ~]=fileparts(app.scriptsFile);
            newname=fullfile(pn,strcat(fn,'_active.xlsx'));
            [fn, pn]=uiputfile('*.xlsx','Name to save Active and Falsh Tables',newname);
            newfile=fullfile(pn,fn);
            writetable(newAF,newfile);
        end

        % Button pushed function: LocateFileButton
        function LocateFileButtonPushed(app, event)
            if isempty(app.activeRow)
                return
            end
            % get the id from the table
            % get the current path from the library variable
            % get a new path
            % compare file name with current name to make sure not changed
            % update the path in the library variable
            % update the tables from the variables
            
            % get the current filename to compare with the new file name, in case wrong file is selected
            row=app.activeRow; % get the row of table to change
            id = app.LibraryTable.Data{row, app.colLibID};  % get the id because library is arranged by id which must be unique
            currentpath=app.scripts.library{id}.path; % current path in library
            [~,name,ext]=fileparts(currentpath);
            cfn=strcat(name,ext);
            % get new file name based on currently listed filename
            [nfn,npn]=uigetfile('*.nnpscript','Locate intended file',currentpath);
            if ~strcmp(nfn,cfn)
                % ask for confirmation for change in filename if they are different
                msg=sprintf('You have chosen a different filename. old=%s  new=%s,  either retry or chose NEW button',cfn,nfn);
                msgbox(msg)
            else
                newpath=fullfile(npn,nfn);
                app.scripts.library{id}.path=newpath;
            end
            updateAllTables(app); % update the tables to match the library variable
        end

        % Button pushed function: TipsButton
        function TipsButtonPushed(app, event)
%          create this alert box because the tip from the table is shown on the right 
            uialert(app.UIFigure,app.libraryTips,'Library Table Tips','Icon','');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Scripts';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Text = 'Save As';
            app.ExportMenu = uimenu(app.UIFigure);
            app.ExportMenu.Text = 'Export';
            app.ExportLibraryMenu = uimenu(app.ExportMenu);
            app.ExportLibraryMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportLibraryMenuSelected, true);
            app.ExportLibraryMenu.Text = 'Library Table';
            app.ActiveandFlashTablesMenu = uimenu(app.ExportMenu);
            app.ActiveandFlashTablesMenu.MenuSelectedFcn = createCallbackFcn(app, @ActiveandFlashTablesMenuSelected, true);
            app.ActiveandFlashTablesMenu.Text = 'Active and Flash Tables';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Text = 'About';
            app.ToolTipsMenu = uimenu(app.HelpMenu);
            app.ToolTipsMenu.Text = 'ToolTips';
            app.EnableMenu = uimenu(app.ToolTipsMenu);
            app.EnableMenu.MenuSelectedFcn = createCallbackFcn(app, @EnableMenuSelected, true);
            app.EnableMenu.Text = 'Enable';
            app.DisableMenu = uimenu(app.ToolTipsMenu);
            app.DisableMenu.MenuSelectedFcn = createCallbackFcn(app, @DisableMenuSelected, true);
            app.DisableMenu.Text = 'Disable';
            app.ScriptingMenu = uimenu(app.HelpMenu);
            app.ScriptingMenu.MenuSelectedFcn = createCallbackFcn(app, @ScriptingMenuSelected, true);
            app.ScriptingMenu.Text = 'Scripting';
            app.ReenableButtonsMenu = uimenu(app.HelpMenu);
            app.ReenableButtonsMenu.MenuSelectedFcn = createCallbackFcn(app, @ReenableButtonsMenuSelected, true);
            app.ReenableButtonsMenu.Text = 'Reenable Buttons';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ImplantTab = uitab(app.TabGroup);
            app.ImplantTab.Title = 'Implant';
            app.ActiveTable = uitable(app.ImplantTab);
            app.ActiveTable.ColumnName = {'-'; 'ID'; 'Name*'; 'File Status*'; 'Sched'; 'Runtime Status'};
            app.ActiveTable.ColumnEditable = [true false false false false false];
            app.ActiveTable.CellEditCallback = createCallbackFcn(app, @ActiveTableCellEdit, true);
            app.ActiveTable.Tooltip = {''};
            app.CheckBox = uicheckbox(app.ImplantTab);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.FlashTable = uitable(app.ImplantTab);
            app.FlashTable.ColumnName = {'ID'; 'Rev'; 'Size'; 'CRC'; '='};
            app.FlashTable.ColumnEditable = [false false false false true];
            app.FlashTable.CellEditCallback = createCallbackFcn(app, @FlashTableCellEdit, true);
            app.FlashTable.Tooltip = {''};
            app.RefreshButton = uibutton(app.ImplantTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.EventHistoryLabel = uilabel(app.ImplantTab);
            app.EventHistoryLabel.Text = {'Event '; 'History'};
            app.EventHistoryTextArea = uitextarea(app.ImplantTab);
            app.Label = uilabel(app.ImplantTab);
            app.Label.Text = '#';
            app.ForceIDsButton = uibutton(app.ImplantTab, 'push');
            app.ForceIDsButton.ButtonPushedFcn = createCallbackFcn(app, @ForceIDsButtonPushed, true);
            app.ForceIDsButton.Text = '←Force IDs';
            app.ScriptControlsPanel = uipanel(app.ImplantTab);
            app.ScriptControlsPanel.Title = 'Script Controls';
            app.StartScriptButton = uibutton(app.ScriptControlsPanel, 'push');
            app.StartScriptButton.ButtonPushedFcn = createCallbackFcn(app, @StartScriptButtonPushed, true);
            app.StartScriptButton.Text = 'Start Script';
            app.StopScriptButton = uibutton(app.ScriptControlsPanel, 'push');
            app.StopScriptButton.ButtonPushedFcn = createCallbackFcn(app, @StopScriptButtonPushed, true);
            app.StopScriptButton.Text = 'Stop Script';
            app.RunScriptOnceButton = uibutton(app.ScriptControlsPanel, 'push');
            app.RunScriptOnceButton.ButtonPushedFcn = createCallbackFcn(app, @RunScriptOnceButtonPushed, true);
            app.RunScriptOnceButton.Text = 'Run Script Once';
            app.RunScriptTimedButton = uibutton(app.ScriptControlsPanel, 'push');
            app.RunScriptTimedButton.ButtonPushedFcn = createCallbackFcn(app, @RunScriptTimedButtonPushed, true);
            app.RunScriptTimedButton.Text = 'Run Script Timed';
            app.ClearRunOnceButton = uibutton(app.ScriptControlsPanel, 'push');
            app.ClearRunOnceButton.ButtonPushedFcn = createCallbackFcn(app, @ClearRunOnceButtonPushed, true);
            app.ClearRunOnceButton.Text = 'Clear Run Once';
            app.DeleteScriptfromPMButton = uibutton(app.ScriptControlsPanel, 'push');
            app.DeleteScriptfromPMButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteScriptfromPMButtonPushed, true);
            app.DeleteScriptfromPMButton.Text = 'Delete Script from PM';
            app.ReadScriptfromPMButton = uibutton(app.ScriptControlsPanel, 'push');
            app.ReadScriptfromPMButton.ButtonPushedFcn = createCallbackFcn(app, @ReadScriptfromPMButtonPushed, true);
            app.ReadScriptfromPMButton.Text = 'Read Script from PM';
            app.ScriptsEnableButton = uibutton(app.ImplantTab, 'state');
            app.ScriptsEnableButton.ValueChangedFcn = createCallbackFcn(app, @ScriptsEnabledButtonValueChanged, true);
            app.ScriptsEnableButton.Text = {'Scripts Enable'; 'Status Unknown'};
            app.VerifyTable = uitable(app.ImplantTab);
            app.VerifyTable.ColumnName = {'Verif'};
            app.VerifyTable.ColumnEditable = true;
            app.VerifyTable.CellEditCallback = createCallbackFcn(app, @VerifyTableCellEdit, true);
            app.VerifyTable.Tooltip = {''};
            app.Label_2 = uilabel(app.ImplantTab);
            app.Label_2.Text = '#';
            app.Label_3 = uilabel(app.ImplantTab);
            app.Label_3.Text = '#';
            app.ScriptSchedulerandScriptOrderButton = uibutton(app.ImplantTab, 'push');
            app.ScriptSchedulerandScriptOrderButton.ButtonPushedFcn = createCallbackFcn(app, @ScriptSchedulerandScriptOrderButtonPushed, true);
            app.ScriptSchedulerandScriptOrderButton.Text = {'Script '; 'Scheduler'; 'and'; 'Script Order'};
            app.ActiveScriptsLabel = uilabel(app.ImplantTab);
            app.ActiveScriptsLabel.Text = 'Active Scripts';
            app.ScriptsStoredinPMFlashLabel = uilabel(app.ImplantTab);
            app.ScriptsStoredinPMFlashLabel.Text = 'Scripts Stored in PM Flash';
            app.VerifyLabel = uilabel(app.ImplantTab);
            app.VerifyLabel.Text = 'Verify';
            app.AbortScriptsButton = uibutton(app.ImplantTab, 'push');
            app.AbortScriptsButton.ButtonPushedFcn = createCallbackFcn(app, @AbortScriptsButtonPushed, true);
            app.AbortScriptsButton.Text = 'Abort Scripts';
            app.UserIDEditField = uieditfield(app.ImplantTab, 'text');
            app.UserIDEditField.Editable = 'off';
            app.ScriptLibraryTab = uitab(app.TabGroup);
            app.ScriptLibraryTab.Title = 'Script Library';
            app.DownloadSelectedButton = uibutton(app.ScriptLibraryTab, 'push');
            app.DownloadSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadSelectedButtonPushed, true);
            app.DownloadSelectedButton.Text = {'Download'; 'Selected'};
            app.AssembleDebugButton = uibutton(app.ScriptLibraryTab, 'push');
            app.AssembleDebugButton.ButtonPushedFcn = createCallbackFcn(app, @AssembleDebugButtonPushed, true);
            app.AssembleDebugButton.Tooltip = {''};
            app.AssembleDebugButton.Text = 'Assemble/Debug';
            app.LibraryTable = uitable(app.ScriptLibraryTab);
            app.LibraryTable.ColumnName = {'>'; '#'; 'ID'; 'DL'; 'Name'; 'Rev'; 'Size'; 'CRC'; 'Downloaded'; 'Download File Modified'; 'Assembled'; 'Script File Modified'; 'Script File Path'; 'Notes'};
            app.LibraryTable.RowName = {};
            app.LibraryTable.ColumnEditable = [false true true true true true false false false false false false true true];
            app.LibraryTable.CellEditCallback = createCallbackFcn(app, @LibraryTableCellEdit, true);
            app.LibraryTable.CellSelectionCallback = createCallbackFcn(app, @LibraryTableCellSelection, true);
            app.LibraryTable.Tooltip = {''};
            app.EditButton = uibutton(app.ScriptLibraryTab, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.AddExistingButton = uibutton(app.ScriptLibraryTab, 'push');
            app.AddExistingButton.ButtonPushedFcn = createCallbackFcn(app, @AddExistingButtonPushed, true);
            app.AddExistingButton.Text = 'Add Existing';
            app.DeleteButton = uibutton(app.ScriptLibraryTab, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.AddNewButton = uibutton(app.ScriptLibraryTab, 'push');
            app.AddNewButton.ButtonPushedFcn = createCallbackFcn(app, @AddNewButtonPushed, true);
            app.AddNewButton.Text = 'Add New';
            app.DownloadAllButton = uibutton(app.ScriptLibraryTab, 'push');
            app.DownloadAllButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadAllButtonPushed, true);
            app.DownloadAllButton.Text = {'Download'; 'All '};
            app.RefreshLibraryTableButton = uibutton(app.ScriptLibraryTab, 'push');
            app.RefreshLibraryTableButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshLibraryTableButtonPushed, true);
            app.RefreshLibraryTableButton.Text = 'Refresh';
            app.ChangeRootFolderButton = uibutton(app.ScriptLibraryTab, 'push');
            app.ChangeRootFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeRootFolderButtonPushed, true);
            app.ChangeRootFolderButton.Tooltip = {'Change common folder, used for moving from one computer to another'};
            app.ChangeRootFolderButton.Text = 'Change Root Folder';
            app.SortIDButton = uibutton(app.ScriptLibraryTab, 'push');
            app.SortIDButton.ButtonPushedFcn = createCallbackFcn(app, @SortIDButtonPushed, true);
            app.SortIDButton.Text = '↓';
            app.SortSPButton = uibutton(app.ScriptLibraryTab, 'push');
            app.SortSPButton.ButtonPushedFcn = createCallbackFcn(app, @SortSPButtonPushed, true);
            app.SortSPButton.Text = '↓';
            app.LocateFileButton = uibutton(app.ScriptLibraryTab, 'push');
            app.LocateFileButton.ButtonPushedFcn = createCallbackFcn(app, @LocateFileButtonPushed, true);
            app.LocateFileButton.Tooltip = {'Locate and update correct path for selected file'};
            app.LocateFileButton.Text = 'Locate File';
            app.TipsButton = uibutton(app.ScriptLibraryTab, 'push');
            app.TipsButton.ButtonPushedFcn = createCallbackFcn(app, @TipsButtonPushed, true);
            app.TipsButton.Text = 'Tips';
            app.SetScriptOrderinSchedulerifPMDefaultsareResetLabel = uilabel(app.ScriptLibraryTab);
            app.SetScriptOrderinSchedulerifPMDefaultsareResetLabel.Text = 'Set Script Order in Scheduler if PM Defaults are Reset!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scriptedit(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end