classdef scripthelp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ScriptHelpFigure     matlab.ui.Figure
        TabGroup             matlab.ui.container.TabGroup
        OpCodesTab           matlab.ui.container.Tab
        OperandsLabel        matlab.ui.control.Label
        UITable              matlab.ui.control.Table
        SyntaxTipsTab        matlab.ui.container.Tab
        NothingHereYetLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Use table format for UITables instead of cell arrays because it updates much faster on scrolling
            % however, we need to set the type within the table, because UITable ignores ColumnFormat if 
            % Data is a table instead of cellarray
            % We want result and jump to show up as checkboxes, so they should be logicals instead of doubles
            
            list = assembler.opcodelist(:,1:7);
            result = logical(cell2mat(list(:,6)));
            list(:,6) = mat2cell(result, ones(length(result),1));
            jump = logical(cell2mat(list(:,7)));
            list(:,7) = mat2cell(jump, ones(length(jump),1));
            table = cell2table(list);
            app.UITable.Data = table;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ScriptHelpFigure = uifigure('Visible', 'off');
            app.ScriptHelpFigure.Name = 'Script Help';
            app.TabGroup = uitabgroup(app.ScriptHelpFigure);
            app.OpCodesTab = uitab(app.TabGroup);
            app.OpCodesTab.Title = 'OpCodes';
            app.UITable = uitable(app.OpCodesTab);
            app.UITable.ColumnName = {'OPCODE'; 'Byte'; 'Min'; 'Max'; 'Description'; 'Res'; 'Jump'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true true true true true true true];
            app.UITable.ColumnEditable = [false false false false false false false];
            app.OperandsLabel = uilabel(app.OpCodesTab);
            app.OperandsLabel.Text = 'Operands';
            app.SyntaxTipsTab = uitab(app.TabGroup);
            app.SyntaxTipsTab.Title = 'Syntax Tips';
            app.NothingHereYetLabel = uilabel(app.SyntaxTipsTab);
            app.NothingHereYetLabel.Text = 'Nothing Here Yet';
            % Show the figure after all components are created
            app.ScriptHelpFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scripthelp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ScriptHelpFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ScriptHelpFigure)
        end
    end
end