classdef scriptorder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UITable                    matlab.ui.control.Table
        ReadButton                 matlab.ui.control.Button
        WriteButton                matlab.ui.control.Button
        ScriptSchedulerOrderLabel  matlab.ui.control.Label
        OrdLabel                   matlab.ui.control.Label
        noscriptinslotLabel        matlab.ui.control.Label
    end

    
    properties (Access = public)
        SED = []; %handle to scriptedit
        reqOrder;
    end
    
    properties (Access = private)
        colCurrent = 1;
        colNew = 2;
        changedOD = false;
    end
    
    methods (Access = private)
        
        function populateTable(app)
            if isempty(app.UITable.Data)
                data = cell(25,2);
                if ~isempty(app.SED.scripts) && isfield(app.SED.scripts, 'order') && ~isempty(app.SED.scripts.order)
                    app.reqOrder = app.SED.scripts.order;
                end
            else
                data = app.UITable.Data;
            end
            resp = double(app.SED.nnp.read(7, '1f56', 1));
            if length(resp) ~=25
                app.ReadButton.BackgroundColor = [1 0 0];
            else
                app.ReadButton.BackgroundColor = [.96 .96 .96];
                data(:,app.colCurrent) = num2cell(resp');
                if isempty(app.reqOrder)
                    app.reqOrder = resp'; %default New values are same as current
                end
            end
            data(:,app.colNew) = num2cell(app.reqOrder);
            app.UITable.Data = data;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, SED)
            app.SED = SED;
            app.populateTable();
        end

        % Button pushed function: ReadButton
        function ReadButtonPushed(app, event)
            app.populateTable();
        end

        % Button pushed function: WriteButton
        function WriteButtonPushed(app, event)

            data = cell2mat(app.UITable.Data(:, app.colNew));

            if all(isnumeric(data)) && all(data <= 25) && all(data >= 0) %order = 0 means no execution in round robin.  Script c
                app.reqOrder = data;
                app.changedOD = true;
                resp = app.SED.nnp.write(7, '1f56', 1, data');
                if isequal(resp, 0)
                    app.WriteButton.BackgroundColor = [.96 .96 .96];
                else
                    app.WriteButton.BackgroundColor = [1 0 0];
                end
                app.populateTable();
                if ~isempty(app.SED)
                    app.SED.populateActiveTable();
                end
            else
                msgbox('At least one value in New column is outside range 0-25')
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isequal(app.reqOrder, cell2mat(app.UITable.Data(:, app.colNew))) %unapplied changes
                userResp = questdlg('Values were changed in table but not sent to Implant. Do you want to go back and apply changes?');
                if ~isequal(userResp, 'No')
                    return
                end
            else
            
                if app.changedOD
                    userResp = questdlg('Do you want to save the new script order values as defaults in the Object Dictionary?');
                    if isequal(userResp, 'Yes')
                        app.nnp.nmt(7, '0a');
                    elseif isequal(userResp, 'No')
                        %nothing to do
                    else %cancel
                        return %don't close app
                    end
                end
            
                if ~isempty(app.reqOrder)
                    
                    if isempty(app.SED.scripts) || ~isfield(app.SED.scripts, 'order') || ....
                             ~isequal(app.reqOrder, app.SED.scripts.order) %changes tp file order
         
                        userResp = questdlg('Do you want to save the new script order values to the Script Library file?');
                        if isequal(userResp, 'Yes')
                            app.SED.scripts.order = app.reqOrder;
                        elseif isequal(userResp, 'No')
                            %nothing to do
                        else %cancel
                            return %don't close app
                        end
                    end
                end
            end

            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Current #'; 'New #'};
            app.UITable.ColumnEditable = [false true];
            app.ReadButton = uibutton(app.UIFigure, 'push');
            app.ReadButton.ButtonPushedFcn = createCallbackFcn(app, @ReadButtonPushed, true);
            app.ReadButton.Text = 'Read';
            app.WriteButton = uibutton(app.UIFigure, 'push');
            app.WriteButton.ButtonPushedFcn = createCallbackFcn(app, @WriteButtonPushed, true);
            app.WriteButton.Text = 'Write';
            app.ScriptSchedulerOrderLabel = uilabel(app.UIFigure);
            app.ScriptSchedulerOrderLabel.Text = 'Script Scheduler Order';
            app.OrdLabel = uilabel(app.UIFigure);
            app.OrdLabel.Text = 'Ord';
            app.noscriptinslotLabel = uilabel(app.UIFigure);
            app.noscriptinslotLabel.Text = '(0 = no script # in slot)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scriptorder(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end