classdef scripts < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ScriptsUIFigure                 matlab.ui.Figure
        ScriptTable                     matlab.ui.control.Table
        CheckBox                        matlab.ui.control.CheckBox
        RunScriptOnceButton             matlab.ui.control.Button
        CheckStatusButton               matlab.ui.control.Button
        DeleteScriptButton              matlab.ui.control.Button
        RefreshButton                   matlab.ui.control.Button
        StartScriptButton               matlab.ui.control.Button
        StopScriptButton                matlab.ui.control.Button
        RunScriptTimedButton            matlab.ui.control.Button
        ReadButton                      matlab.ui.control.Button
        WriteButton                     matlab.ui.control.Button
        ScriptExecutionPeriodEditFieldLabel  matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        AsssemblerButton                matlab.ui.control.Button
        DownloadButton                  matlab.ui.control.Button
        PDOTimerTriggeredScriptsButton  matlab.ui.control.Button
        ScriptsEnabledSwitchLabel       matlab.ui.control.Label
        ScriptsEnabledSwitch            matlab.ui.control.Switch
        ScriptExecutionPeriodEditField  matlab.ui.control.NumericEditField
        EventHistoryTextAreaLabel       matlab.ui.control.Label
        EventHistoryTextArea            matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        nnp = []; % Description
    end
    
    methods (Access = private)
        
        function populateTable(app)
            data = cell(25,5);
            app.ScriptTable.Data = data;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, NNP)
            if isempty(NNP)
                app.nnp = NNPAPI();
                if isempty(app.nnp.port)
                    msgbox('No port chosen for NNP Access Point')
                    delete(app.nnp)
                    return
                end
            else
                app.nnp = NNP;
            end
            
            app.populateTable();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ScriptsUIFigure = uifigure('Visible', 'off');
            app.ScriptsUIFigure.Name = 'Scripts';
            app.ScriptTable = uitable(app.ScriptsUIFigure);
            app.ScriptTable.ColumnName = {'Select'; 'ScriptID'; 'Name'; 'Order'; 'Size'};
            app.ScriptTable.RowName = {''};
            app.CheckBox = uicheckbox(app.ScriptsUIFigure);
            app.CheckBox.Text = '';
            app.RunScriptOnceButton = uibutton(app.ScriptsUIFigure, 'push');
            app.RunScriptOnceButton.Text = 'Run Script Once';
            app.CheckStatusButton = uibutton(app.ScriptsUIFigure, 'push');
            app.CheckStatusButton.Text = 'Check Status';
            app.DeleteScriptButton = uibutton(app.ScriptsUIFigure, 'push');
            app.DeleteScriptButton.Text = 'Delete Script';
            app.RefreshButton = uibutton(app.ScriptsUIFigure, 'push');
            app.RefreshButton.Text = 'Refresh';
            app.StartScriptButton = uibutton(app.ScriptsUIFigure, 'push');
            app.StartScriptButton.Text = 'Start Script';
            app.StopScriptButton = uibutton(app.ScriptsUIFigure, 'push');
            app.StopScriptButton.Text = 'Stop Script';
            app.RunScriptTimedButton = uibutton(app.ScriptsUIFigure, 'push');
            app.RunScriptTimedButton.Text = 'Run Script Timed';
            app.ReadButton = uibutton(app.ScriptsUIFigure, 'push');
            app.ReadButton.Text = 'Read';
            app.WriteButton = uibutton(app.ScriptsUIFigure, 'push');
            app.WriteButton.Text = 'Write';
            app.EventHistoryTextAreaLabel = uilabel(app.ScriptsUIFigure);
            app.EventHistoryTextAreaLabel.Text = 'Event History';
            app.EventHistoryTextArea = uitextarea(app.ScriptsUIFigure);
            app.ScriptExecutionPeriodEditFieldLabel = uilabel(app.ScriptsUIFigure);
            app.ScriptExecutionPeriodEditFieldLabel.Text = 'Script Execution Period';
            app.ScriptExecutionPeriodEditField = uieditfield(app.ScriptsUIFigure, 'numeric');
            app.ScriptExecutionPeriodEditField.Limits = [80 150];
            app.ScriptExecutionPeriodEditField.Value = 100;
            app.msLabel = uilabel(app.ScriptsUIFigure);
            app.msLabel.Text = 'ms';
            app.ScriptsEnabledSwitchLabel = uilabel(app.ScriptsUIFigure);
            app.ScriptsEnabledSwitchLabel.Text = 'Scripts Enabled';
            app.ScriptsEnabledSwitch = uiswitch(app.ScriptsUIFigure, 'slider');
            app.AsssemblerButton = uibutton(app.ScriptsUIFigure, 'push');
            app.AsssemblerButton.Text = 'Asssembler';
            app.DownloadButton = uibutton(app.ScriptsUIFigure, 'push');
            app.DownloadButton.Text = 'Download';
            app.PDOTimerTriggeredScriptsButton = uibutton(app.ScriptsUIFigure, 'push');
            app.PDOTimerTriggeredScriptsButton.Text = {'PDO/Timer '; 'Triggered'; 'Scripts'};
            % Show the figure after all components are created
            app.ScriptsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scripts(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ScriptsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ScriptsUIFigure)
        end
    end
end