classdef scriptsched < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ScriptSchedulerUIFigure  matlab.ui.Figure
        noscriptinslotLabel      matlab.ui.control.Label
        RTCPanel                 matlab.ui.container.Panel
        AlarmEnabledCheckBox     matlab.ui.control.CheckBox
        Alarm1EditFieldLabel     matlab.ui.control.Label
        Alarm1TimeEditField      matlab.ui.control.EditField
        Alarm2EditFieldLabel     matlab.ui.control.Label
        Alarm2TimeEditField      matlab.ui.control.EditField
        Alarm3EditFieldLabel     matlab.ui.control.Label
        Alarm3TimeEditField      matlab.ui.control.EditField
        Alarm4EditFieldLabel     matlab.ui.control.Label
        Alarm4TimeEditField      matlab.ui.control.EditField
        HHMMSSLabel              matlab.ui.control.Label
        Alarm1SPEditField        matlab.ui.control.NumericEditField
        Alarm2SPEditField        matlab.ui.control.NumericEditField
        Alarm3SPEditField        matlab.ui.control.NumericEditField
        Alarm4SPEditField        matlab.ui.control.NumericEditField
        Label                    matlab.ui.control.Label
        EditField                matlab.ui.control.EditField
        GetImplantTimeButton     matlab.ui.control.Button
        GetComputerTimeButton    matlab.ui.control.Button
        SetImplantTimeButton     matlab.ui.control.Button
        RTCRefreshButton         matlab.ui.control.Button
        RPDOPanel                matlab.ui.container.Panel
        RPDOEnabledCheckBox      matlab.ui.control.CheckBox
        RPDO1Label               matlab.ui.control.Label
        RPDO1AddrEditField       matlab.ui.control.NumericEditField
        RPDO2Label               matlab.ui.control.Label
        RPDO2AddrEditField       matlab.ui.control.NumericEditField
        RPDO3Label               matlab.ui.control.Label
        RPDO3AddrEditField       matlab.ui.control.NumericEditField
        RPDO4Label               matlab.ui.control.Label
        RPDO4AddrEditField       matlab.ui.control.NumericEditField
        RPDO5Label               matlab.ui.control.Label
        RPDO5AddrEditField       matlab.ui.control.NumericEditField
        RPDO6Label               matlab.ui.control.Label
        RPDO6AddrEditField       matlab.ui.control.NumericEditField
        RPDO7Label               matlab.ui.control.Label
        RPDO7AddrEditField       matlab.ui.control.NumericEditField
        RPDO8Label               matlab.ui.control.Label
        RPDO8AddrEditField       matlab.ui.control.NumericEditField
        AddressLabel             matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
        RPDO1SPEditField         matlab.ui.control.NumericEditField
        RPDO2SPEditField         matlab.ui.control.NumericEditField
        RPDO3SPEditField         matlab.ui.control.NumericEditField
        RPDO4SPEditField         matlab.ui.control.NumericEditField
        RPDO5SPEditField         matlab.ui.control.NumericEditField
        RPDO6SPEditField         matlab.ui.control.NumericEditField
        RPDO7SPEditField         matlab.ui.control.NumericEditField
        RPDO8SPEditField         matlab.ui.control.NumericEditField
        RPDORefreshButton        matlab.ui.control.Button
        StartUpPanel             matlab.ui.container.Panel
        Label_3                  matlab.ui.control.Label
        StartupEditField         matlab.ui.control.NumericEditField
        StartupEnabledCheckBox   matlab.ui.control.CheckBox
        RefreshStartupButton     matlab.ui.control.Button
        OrderPanel               matlab.ui.container.Panel
        UITable                  matlab.ui.control.Table
        ReadButton               matlab.ui.control.Button
        WriteButton              matlab.ui.control.Button
        OrdLabel                 matlab.ui.control.Label
        SchedulerLabel           matlab.ui.control.Label
        ScriptPeriodEditField    matlab.ui.control.NumericEditField
        msLabel                  matlab.ui.control.Label
        RefreshPeriodButton      matlab.ui.control.Button
        ScriptExecutionPeriodEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        SED = []; %handle to scriptedit
        reqOrder;
    end
    
    properties (Access = private)
        colCurrent = 1;
        colNew = 2;
        changedOD = false;
    end
    
    methods (Access = private)
        
        function populateTable(app)
            if isempty(app.UITable.Data)
                data = cell(25,2);
                if ~isempty(app.SED.scripts) && isfield(app.SED.scripts, 'order') && ~isempty(app.SED.scripts.order)
                    app.reqOrder = app.SED.scripts.order;
                end
            else
                data = app.UITable.Data;
            end
            resp = double(app.SED.nnp.read(7, '1f56', 1));
            if length(resp) ~=25
                app.ReadButton.BackgroundColor = [1 0 0];
            else
                app.ReadButton.BackgroundColor = [.96 .96 .96];
                data(:,app.colCurrent) = num2cell(resp');
                if isempty(app.reqOrder)
                    app.reqOrder = resp'; %default New values are same as current
                end
            end
            data(:,app.colNew) = num2cell(app.reqOrder);
            app.UITable.Data = data;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, SED)
            app.SED = SED;
            app.populateTable();
        end

        % Button pushed function: ReadButton
        function ReadButtonPushed(app, event)
            app.populateTable();
        end

        % Button pushed function: WriteButton
        function WriteButtonPushed(app, event)

            data = cell2mat(app.UITable.Data(:, app.colNew));

            if all(isnumeric(data)) && all(data <= 25) && all(data >= 0) %order = 0 means no execution in round robin.  Script c
                app.reqOrder = data;
                app.changedOD = true;
                resp = app.SED.nnp.write(7, '1f56', 1, data');
                if isequal(resp, 0)
                    app.WriteButton.BackgroundColor = [.96 .96 .96];
                else
                    app.WriteButton.BackgroundColor = [1 0 0];
                end
                app.populateTable();
                if ~isempty(app.SED)
                    app.SED.populateActiveTable();
                end
            else
                msgbox('At least one value in New column is outside range 0-25')
            end
        end

        % Close request function: ScriptSchedulerUIFigure
        function ScriptSchedulerUIFigureCloseRequest(app, event)
            if ~isequal(app.reqOrder, cell2mat(app.UITable.Data(:, app.colNew))) %unapplied changes
                userResp = questdlg('Values were changed in table but not sent to Implant. Do you want to go back and apply changes?');
                if ~isequal(userResp, 'No')
                    return
                end
            else
            
                if app.changedOD
                    userResp = questdlg('Do you want to save the new script order values as defaults in the Object Dictionary?');
                    if isequal(userResp, 'Yes')
                        app.nnp.nmt(7, '0a');
                    elseif isequal(userResp, 'No')
                        %nothing to do
                    else %cancel
                        return %don't close app
                    end
                end
            
                if ~isempty(app.reqOrder)
                    
                    if isempty(app.SED.scripts) || ~isfield(app.SED.scripts, 'order') || ....
                             ~isequal(app.reqOrder, app.SED.scripts.order) %changes tp file order
         
                        userResp = questdlg('Do you want to save the new script order values to the Script Library file?');
                        if isequal(userResp, 'Yes')
                            app.SED.scripts.order = app.reqOrder;
                        elseif isequal(userResp, 'No')
                            %nothing to do
                        else %cancel
                            return %don't close app
                        end
                    end
                end
            end

            delete(app)
            
        end

        % Button pushed function: RefreshPeriodButton
        function RefreshPeriodButtonPushed(app, event)
            resp = double(app.nnp.read(7, '2001', 5, 'uint32'));
            if length(resp)==1
                app.ScriptPeriodEditField.Value = resp;
                app.RefreshPeriodButton.BackgroundColor = [.96 .96 .96];
            else
                app.RefreshPeriodButton.BackgroundColor = [1 0 0];
            end
        end

        % Value changed function: ScriptPeriodEditField
        function ScriptPeriodEditFieldValueChanged(app, event)
            period = app.ScriptPeriodEditField.Value;
            resp = app.nnp.write(7, '2001', 5, period, 'uint32');
            if ~isequal(resp, 0) %did not get acknowledgement, read it back
                app.RefreshPeriodButtonPushed([]);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ScriptSchedulerUIFigure = uifigure('Visible', 'off');
            app.ScriptSchedulerUIFigure.Name = 'Script Scheduler';
            app.ScriptSchedulerUIFigure.CloseRequestFcn = createCallbackFcn(app, @ScriptSchedulerUIFigureCloseRequest, true);
            app.noscriptinslotLabel = uilabel(app.ScriptSchedulerUIFigure);
            app.noscriptinslotLabel.Text = '(#: 0 = no script in slot)';
            app.RTCPanel = uipanel(app.ScriptSchedulerUIFigure);
            app.RTCPanel.Tooltip = {'Script (#) is queued when Real-time Clock reaches Alarm Time'};
            app.AlarmEnabledCheckBox = uicheckbox(app.RTCPanel);
            app.AlarmEnabledCheckBox.Text = 'Real-Time Clock';
            app.Alarm1EditFieldLabel = uilabel(app.RTCPanel);
            app.Alarm1EditFieldLabel.Text = 'Alarm 1';
            app.Alarm1TimeEditField = uieditfield(app.RTCPanel, 'text');
            app.Alarm2EditFieldLabel = uilabel(app.RTCPanel);
            app.Alarm2EditFieldLabel.Text = 'Alarm 2';
            app.Alarm2TimeEditField = uieditfield(app.RTCPanel, 'text');
            app.Alarm3EditFieldLabel = uilabel(app.RTCPanel);
            app.Alarm3EditFieldLabel.Text = 'Alarm 3';
            app.Alarm3TimeEditField = uieditfield(app.RTCPanel, 'text');
            app.Alarm4EditFieldLabel = uilabel(app.RTCPanel);
            app.Alarm4EditFieldLabel.Text = 'Alarm 4';
            app.Alarm4TimeEditField = uieditfield(app.RTCPanel, 'text');
            app.HHMMSSLabel = uilabel(app.RTCPanel);
            app.HHMMSSLabel.Text = 'HH:MM:SS';
            app.Alarm1SPEditField = uieditfield(app.RTCPanel, 'numeric');
            app.Alarm1SPEditField.Limits = [0 25];
            app.Alarm2SPEditField = uieditfield(app.RTCPanel, 'numeric');
            app.Alarm2SPEditField.Limits = [0 25];
            app.Alarm3SPEditField = uieditfield(app.RTCPanel, 'numeric');
            app.Alarm3SPEditField.Limits = [0 25];
            app.Alarm4SPEditField = uieditfield(app.RTCPanel, 'numeric');
            app.Alarm4SPEditField.Limits = [0 25];
            app.Label = uilabel(app.RTCPanel);
            app.Label.Text = '#';
            app.EditField = uieditfield(app.RTCPanel, 'text');
            app.EditField.Value = '00:00:00';
            app.GetImplantTimeButton = uibutton(app.RTCPanel, 'push');
            app.GetImplantTimeButton.Text = 'Get Implant Time';
            app.GetComputerTimeButton = uibutton(app.RTCPanel, 'push');
            app.GetComputerTimeButton.Text = 'Get Computer Time';
            app.SetImplantTimeButton = uibutton(app.RTCPanel, 'push');
            app.SetImplantTimeButton.Text = 'Set Implant Time';
            app.RTCRefreshButton = uibutton(app.RTCPanel, 'push');
            app.RTCRefreshButton.Text = 'Refresh';
            app.RPDOPanel = uipanel(app.ScriptSchedulerUIFigure);
            app.RPDOPanel.Tooltip = {'Script (#) is queued when data received by PDO'};
            app.RPDOEnabledCheckBox = uicheckbox(app.RPDOPanel);
            app.RPDOEnabledCheckBox.Text = 'Received Data';
            app.RPDO1Label = uilabel(app.RPDOPanel);
            app.RPDO1Label.Text = 'RPDO 1';
            app.RPDO1AddrEditField = uieditfield(app.RPDOPanel, 'numeric');
            app.RPDO1AddrEditField.ValueDisplayFormat = '%03X';
            app.RPDO1AddrEditField.Editable = 'off';
            app.RPDO2Label = uilabel(app.RPDOPanel);
            app.RPDO2Label.Text = 'RPDO 2';
            app.RPDO2AddrEditField = uieditfield(app.RPDOPanel, 'numeric');
            app.RPDO2AddrEditField.ValueDisplayFormat = '%03X';
            app.RPDO2AddrEditField.Editable = 'off';
            app.RPDO3Label = uilabel(app.RPDOPanel);
            app.RPDO3Label.Text = 'RPDO 3';
            app.RPDO3AddrEditField = uieditfield(app.RPDOPanel, 'numeric');
            app.RPDO3AddrEditField.ValueDisplayFormat = '%03X';
            app.RPDO3AddrEditField.Editable = 'off';
            app.RPDO4Label = uilabel(app.RPDOPanel);
            app.RPDO4Label.Text = 'RPDO 4';
            app.RPDO4AddrEditField = uieditfield(app.RPDOPanel, 'numeric');
            app.RPDO4AddrEditField.ValueDisplayFormat = '%03X';
            app.RPDO4AddrEditField.Editable = 'off';
            app.RPDO5Label = uilabel(app.RPDOPanel);
            app.RPDO5Label.Text = 'RPDO 5';
            app.RPDO5AddrEditField = uieditfield(app.RPDOPanel, 'numeric');
            app.RPDO5AddrEditField.ValueDisplayFormat = '%03X';
            app.RPDO5AddrEditField.Editable = 'off';
            app.RPDO6Label = uilabel(app.RPDOPanel);
            app.RPDO6Label.Text = 'RPDO 6';
            app.RPDO6AddrEditField = uieditfield(app.RPDOPanel, 'numeric');
            app.RPDO6AddrEditField.ValueDisplayFormat = '%03X';
            app.RPDO6AddrEditField.Editable = 'off';
            app.RPDO7Label = uilabel(app.RPDOPanel);
            app.RPDO7Label.Text = 'RPDO 7';
            app.RPDO7AddrEditField = uieditfield(app.RPDOPanel, 'numeric');
            app.RPDO7AddrEditField.ValueDisplayFormat = '%03X';
            app.RPDO7AddrEditField.Editable = 'off';
            app.RPDO8Label = uilabel(app.RPDOPanel);
            app.RPDO8Label.Text = 'RPDO 8';
            app.RPDO8AddrEditField = uieditfield(app.RPDOPanel, 'numeric');
            app.RPDO8AddrEditField.ValueDisplayFormat = '%03X';
            app.RPDO8AddrEditField.Editable = 'off';
            app.AddressLabel = uilabel(app.RPDOPanel);
            app.AddressLabel.Text = 'Address';
            app.Label_2 = uilabel(app.RPDOPanel);
            app.Label_2.Text = '#';
            app.RPDO1SPEditField = uieditfield(app.RPDOPanel, 'numeric');
            app.RPDO1SPEditField.Limits = [0 25];
            app.RPDO2SPEditField = uieditfield(app.RPDOPanel, 'numeric');
            app.RPDO2SPEditField.Limits = [0 25];
            app.RPDO3SPEditField = uieditfield(app.RPDOPanel, 'numeric');
            app.RPDO3SPEditField.Limits = [0 25];
            app.RPDO4SPEditField = uieditfield(app.RPDOPanel, 'numeric');
            app.RPDO4SPEditField.Limits = [0 25];
            app.RPDO5SPEditField = uieditfield(app.RPDOPanel, 'numeric');
            app.RPDO5SPEditField.Limits = [0 25];
            app.RPDO6SPEditField = uieditfield(app.RPDOPanel, 'numeric');
            app.RPDO6SPEditField.Limits = [0 25];
            app.RPDO7SPEditField = uieditfield(app.RPDOPanel, 'numeric');
            app.RPDO7SPEditField.Limits = [0 25];
            app.RPDO8SPEditField = uieditfield(app.RPDOPanel, 'numeric');
            app.RPDO8SPEditField.Limits = [0 25];
            app.RPDORefreshButton = uibutton(app.RPDOPanel, 'push');
            app.RPDORefreshButton.Text = 'Refresh';
            app.StartUpPanel = uipanel(app.ScriptSchedulerUIFigure);
            app.StartUpPanel.Tooltip = {'Script(#) is executed on power up of the Implant.  These script is usually used to start other scripts.'};
            app.Label_3 = uilabel(app.StartUpPanel);
            app.Label_3.Text = '#';
            app.StartupEditField = uieditfield(app.StartUpPanel, 'numeric');
            app.StartupEditField.Limits = [0 25];
            app.StartupEnabledCheckBox = uicheckbox(app.StartUpPanel);
            app.StartupEnabledCheckBox.Text = 'Startup';
            app.RefreshStartupButton = uibutton(app.StartUpPanel, 'push');
            app.RefreshStartupButton.Text = 'Refresh';
            app.OrderPanel = uipanel(app.ScriptSchedulerUIFigure);
            app.OrderPanel.Tooltip = {'Scripts (#) are queued every period in the indicated order.'; 'To make a script execute less frequently use a global counter within the script and exit early if the script doesn''t need to execute.'};
            app.UITable = uitable(app.OrderPanel);
            app.UITable.ColumnName = {'Current #'; 'New #'};
            app.UITable.ColumnEditable = [false true];
            app.ReadButton = uibutton(app.OrderPanel, 'push');
            app.ReadButton.ButtonPushedFcn = createCallbackFcn(app, @ReadButtonPushed, true);
            app.ReadButton.Text = 'Read';
            app.WriteButton = uibutton(app.OrderPanel, 'push');
            app.WriteButton.ButtonPushedFcn = createCallbackFcn(app, @WriteButtonPushed, true);
            app.WriteButton.Text = 'Write';
            app.OrdLabel = uilabel(app.OrderPanel);
            app.OrdLabel.Text = 'Ord';
            app.SchedulerLabel = uilabel(app.OrderPanel);
            app.SchedulerLabel.Text = 'Scheduler ';
            app.ScriptPeriodEditField = uieditfield(app.OrderPanel, 'numeric');
            app.ScriptPeriodEditField.Limits = [10 10000];
            app.ScriptPeriodEditField.ValueChangedFcn = createCallbackFcn(app, @ScriptPeriodEditFieldValueChanged, true);
            app.ScriptPeriodEditField.Tooltip = {''};
            app.ScriptPeriodEditField.Value = 100;
            app.msLabel = uilabel(app.OrderPanel);
            app.msLabel.Text = 'ms';
            app.RefreshPeriodButton = uibutton(app.OrderPanel, 'push');
            app.RefreshPeriodButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshPeriodButtonPushed, true);
            app.RefreshPeriodButton.Text = 'Refresh';
            app.ScriptExecutionPeriodEditFieldLabel = uilabel(app.OrderPanel);
            app.ScriptExecutionPeriodEditFieldLabel.Text = 'Period';
            % Show the figure after all components are created
            app.ScriptSchedulerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scriptsched(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ScriptSchedulerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ScriptSchedulerUIFigure)
        end
    end
end