classdef variablemonitor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UITable                   matlab.ui.control.Table
        WatchIndexEditFieldLabel  matlab.ui.control.Label
        WatchIndexEditField       matlab.ui.control.NumericEditField
        RefreshButton             matlab.ui.control.Button
        ErrorLabel                matlab.ui.control.Label
    end

    
    properties (Access = public)
        index = 0; % Description
        nnp = [];
        scope = [];
        varStruct = [];
    end
    
    properties (Access = private)
        colAddr = 1; 
        colBytes = 2;
        colName = 3;
        colVarIndex = 4;
        colInit = 5;
        colValue = 6;
    end
    
    methods (Access = public)


        function populateTable(app, debugData)
            if nargin<2
                debugData = [];
            end
            fprintf('\nUsing starting index %d\n', app.index);
                                
            if isempty(debugData)
                if isequal(app.scope, 'stack')
                    resp = app.nnp.read(7,'1f52', 13, 'uint8');
                else %global
                    resp = app.nnp.read(7,'1f52', 14, 'uint8');
                end
                if length(resp)==32
                    debugData = resp;
                end
            end
                    
            maxPointer = -1;
            for k=1:length(app.varStruct)
                if ~isempty(app.varStruct(k).endpointer) && isequal(app.varStruct(k).scope, app.scope)
                    maxPointer = max(maxPointer, app.varStruct(k).endpointer);
                end
            end
            
            fprintf('\n%s: %d', app.scope, maxPointer);
            if maxPointer<0
                app.ErrorLabel.Text = ['No ' app.scope ' variables to monitor'];
                app.ErrorLabel.Visible = true;
            else
                app.ErrorLabel.Visible = false;
            end
            
            data = cell(maxPointer+1,6);
            bgColor = zeros(maxPointer+1,3);
            for row=1:size(data,1)
                p = row-1; %pointer
                data{row,app.colAddr} = p;
                
                if p>=app.index && p<app.index+32
                    data{row,app.colBytes} = sprintf('0x%02X', debugData(row-app.index));
                    bgColor(row,:) = [1 1 1];
                else
                    data{row,app.colBytes} = '-';
                    bgColor(row,:) = [0.9 0.9 0.9];
                end
                %find a variable with pointer matching this index
                k = find(arrayfun(@(x) isequal(x.scope, app.scope) && isequal(x.pointer, p), app.varStruct), 1); 
                if ~isempty(k)
                    data{row,app.colName} = app.varStruct(k).name;
                    
                    initValue = app.varStruct(k).init;
                    type = app.varStruct(k).type;
                    pointer = app.varStruct(k).pointer;
                    nBytes = app.varStruct(k).endpointer - pointer + 1;
                    
                    isNumeric = false;
                    isString = false;
                    try
                        switch type
                            case {'uint8', 'int8'}
                                isNumeric = true;
                                n = 1;
                            case {'uint16', 'int16'}
                                isNumeric = true;
                                n = 2;
                            case {'uint32', 'int32'} 
                                isNumeric = true;
                                n = 4;
                            case {'string'}
                                isString = true;
                                n = 1;
                            otherwise 
                                n = 0;
                        end
                        
                        if n > 0
                            nEl = nBytes/n;
                            for j = 1:nEl
                                data{row+(j-1)*n, app.colVarIndex} = j-1;
                            end
                            if isNumeric
                                for v = 1:length(initValue)
                                    data{row+(v-1)*n, app.colInit} = initValue(v);
                                end
                            elseif isString
                                data{row, app.colInit} = sprintf('%d "%s"', length(initValue), initValue');
                                for v = 2:length(initValue)+1
                                    data{row+(v-1), app.colInit} = initValue(v-1);
                                end
                            else
                                data{row, app.colInit} = '?';
                            end
                        else
                            data{row,app.colInit} = '?';
                        end
                    catch
                        data{row,app.colInit} = '?';
                    end
                    
                    iBegin = app.varStruct(k).pointer-app.index+1;
                    iEnd = app.varStruct(k).endpointer-app.index+1;
                    fprintf('\nrow %d, %d, %d', row, iBegin, iEnd);
                    if iBegin<1 && iEnd<1
                        % don't set value because the debug read doesn't contain 
                        % any values for the variable at this row (address)
                        continue
                    end
                    if iBegin<1
                        %warning('partial read - missing beginning bytes')
                        offset = 1-iBegin;
                        iBegin = 1;
                    else
                        offset = 0;
                    end
                    if iEnd>32
                        %warning('partial read - missing end bytes')
                        iEnd = 32;
                    end
                    
                    bytes = uint8(debugData(iBegin:iEnd))';

                    try
                        
                        if n > 0
                            nRemOffset = rem(offset, n);
                            if  nRemOffset ~= 0
                                bytes = bytes(1+nRemOffset:end);
                                offset = offset+nRemOffset;
                            end
                            nRemBytes = rem(length(bytes),n);
                            if nRemBytes ~= 0 
                                bytes = bytes(1:end-nRemBytes);
                            end
                            
                            if isNumeric
                                value = typecast(bytes, type);
                                for v = 1:length(value)
                                    data{row+(v-1)*n+offset, app.colValue} = value(v);
                                end
                            elseif isString
                                value = char(bytes);
                                if offset==0
                                    data{row, app.colValue} = sprintf('%d "%s"', value(1), value(2:end)');
                                    for v = 2:length(value)
                                        data{row+(v-1), app.colValue} = value(v);
                                    end
                                else
                                    for v = 1:length(value)
                                        data{row+(v-1)+offset, app.colValue} = value(v);
                                    end
                                end
                            end
                           
                        else
                            data{row,app.colValue} = '?';
                        end
                    catch
                        data{row,app.colValue} = '?';
                    end

                end
            end
            emptyData = cellfun(@(x) isempty(x), data); %find empty cells
            data(emptyData)= {'  '}; %convert to char array (must be at least 2 characters or Mtalb convert differently!)
            dataTable = cell2table(data, 'VariableNames', {'Addr', 'Byte', 'Name', 'Index', 'Init', 'Value'});

            app.UITable.Data = dataTable; 
            app.UITable.BackgroundColor = bgColor;
           
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, NNP, scope, varStruct)
             app.nnp = NNP;
             switch scope
                 case {'stack', 'Stack'}
                     app.scope = 'stack';
                     app.UIFigure.Name = 'Stack Variables Monitor';
                 case {'global', 'Global'}
                     app.scope = 'global';
                     app.UIFigure.Name = 'Global Variables Monitor';
                 otherwise
                     error('type must be stack or global')
             end
             app.varStruct = varStruct;
             app.RefreshButtonPushed([]);
             app.populateTable([]);
        end

        % Value changed function: WatchIndexEditField
        function WatchIndexEditFieldValueChanged(app, event)
            value = app.WatchIndexEditField.Value;
            
            if isequal(app.scope, 'stack')
                resp = app.nnp.write(7,'1f52', 18, value, 'uint16');
            else %global
                resp = app.nnp.write(7,'1f52', 19, value, 'uint16');
            end
            if ~isequal(resp, 0) %not confirmed, read back
                app.RefreshButtonPushed(event);
            else
                app.index = value;
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            if isequal(app.scope, 'stack')
                resp = app.nnp.read(7,'1f52', 18, 'uint16');
            else %global
                resp = app.nnp.read(7,'1f52', 19, 'uint16');
            end
            if length(resp)==1
                app.WatchIndexEditField.Value = double(resp);
                app.RefreshButton.BackgroundColor = [0.96 0.96 0.96];
                app.index = double(resp);
            else
                app.RefreshButton.BackgroundColor = [1 0 0];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Variable Table';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Addr'; 'Byte'; 'Name'; 'Index'; 'Init'; 'Value'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [false false false false false false];
            app.UITable.ColumnEditable = [false false false false false false];
            app.WatchIndexEditFieldLabel = uilabel(app.UIFigure);
            app.WatchIndexEditFieldLabel.Text = 'Watch Index';
            app.WatchIndexEditField = uieditfield(app.UIFigure, 'numeric');
            app.WatchIndexEditField.Limits = [0 65535];
            app.WatchIndexEditField.ValueChangedFcn = createCallbackFcn(app, @WatchIndexEditFieldValueChanged, true);
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.ErrorLabel = uilabel(app.UIFigure);
            app.ErrorLabel.Text = 'Error';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = variablemonitor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end