classdef StoryBoard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        MainTabGroup                    matlab.ui.container.TabGroup
        ParticipantTab                  matlab.ui.container.Tab
        ParticipantDropDown             matlab.ui.control.DropDown
        ParticipantDropDownLabel        matlab.ui.control.Label
        NewParticipantButton            matlab.ui.control.Button
        HardwareTab                     matlab.ui.container.Tab
        WewouldhavesimilardisplayforleftarmandPMLabel  matlab.ui.control.Label
        ModulesPanel                    matlab.ui.container.Panel
        LeftLabel_2                     matlab.ui.control.Label
        RightLabel                      matlab.ui.control.Label
        EditField_7                     matlab.ui.control.NumericEditField
        EditField_6                     matlab.ui.control.NumericEditField
        EditField_5                     matlab.ui.control.NumericEditField
        EditField_4                     matlab.ui.control.NumericEditField
        EditField_3                     matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.NumericEditField
        EditField                       matlab.ui.control.NumericEditField
        Image50                         matlab.ui.control.Image
        ChargingPanel                   matlab.ui.container.Panel
        ChargingIntensitySwitch         matlab.ui.control.Switch
        ChargingIntensitySwitchLabel    matlab.ui.control.Label
        PMLocationSwitch                matlab.ui.control.Switch
        PMLocationSwitchLabel           matlab.ui.control.Label
        AgreaterdepthrequiresmorepowertoreachthePMLabel_2  matlab.ui.control.Label
        AgreaterdepthrequiresmorepowertoreachthePMLabel  matlab.ui.control.Label
        OptionsTab                      matlab.ui.container.Tab
        HysteresisPanel                 matlab.ui.container.Panel
        SSminimumEditField              matlab.ui.control.NumericEditField
        SSminimumEditFieldLabel         matlab.ui.control.Label
        HoldsEditField                  matlab.ui.control.NumericEditField
        HoldsEditFieldLabel             matlab.ui.control.Label
        PatternEnableLabel              matlab.ui.control.Label
        Image45                         matlab.ui.control.Image
        TrunkOptionsPanel               matlab.ui.container.Panel
        TricepsControlPanel             matlab.ui.container.Panel
        RampPanel                       matlab.ui.container.Panel
        RampDownEditField               matlab.ui.control.NumericEditField
        RampDownEditFieldLabel          matlab.ui.control.Label
        RampUpEditField                 matlab.ui.control.NumericEditField
        RampUpEditFieldLabel            matlab.ui.control.Label
        DelayEditField                  matlab.ui.control.NumericEditField
        DelayEditFieldLabel             matlab.ui.control.Label
        TricepsControlMethodDropDown    matlab.ui.control.DropDown
        TricepsControlMethodDropDownLabel  matlab.ui.control.Label
        PatternChangeOptionsPanel       matlab.ui.container.Panel
        FavoritesNumEditField           matlab.ui.control.NumericEditField
        FavoritesNumEditFieldLabel      matlab.ui.control.Label
        FavoritesCheckBox               matlab.ui.control.CheckBox
        CycleThrough0CheckBox           matlab.ui.control.CheckBox
        PatternChangeDropDown           matlab.ui.control.DropDown
        PatternChangeDropDownLabel      matlab.ui.control.Label
        LockOptionsPanel                matlab.ui.container.Panel
        LockDisablePanel                matlab.ui.container.Panel
        DisableLocks                    matlab.ui.control.NumericEditField
        DisableLocksLabel               matlab.ui.control.Label
        LockDisableTable                matlab.ui.control.Table
        UnLockDoubletChanDropDown       matlab.ui.control.DropDown
        UnLockDoubletChanDropDownLabel  matlab.ui.control.Label
        LockEMGChanDropDown             matlab.ui.control.DropDown
        LockEMGChanDropDownLabel        matlab.ui.control.Label
        HoldPeriodsEditField            matlab.ui.control.NumericEditField
        HoldPeriodsEditFieldLabel       matlab.ui.control.Label
        HoldEMGCheckBox                 matlab.ui.control.CheckBox
        HoldOnlyCheckBox                matlab.ui.control.CheckBox
        HandPanel                       matlab.ui.container.Panel
        HysteresisCheckBox              matlab.ui.control.CheckBox
        HandControlMethodDropDown       matlab.ui.control.DropDown
        HandControlMethodDropDownLabel  matlab.ui.control.Label
        IncludedMuscleGroupsPanel       matlab.ui.container.Panel
        TrunkCheckBox                   matlab.ui.control.CheckBox
        TricepsCheckBox                 matlab.ui.control.CheckBox
        HandCheckBox                    matlab.ui.control.CheckBox
        NetworkVoltageEditField         matlab.ui.control.NumericEditField
        NetworkVoltageEditFieldLabel    matlab.ui.control.Label
        StatusTab                       matlab.ui.container.Tab
        Image8                          matlab.ui.control.Image
        ProfileTab                      matlab.ui.container.Tab
        Image9                          matlab.ui.control.Image
        PatternsTab                     matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        GroupsTab                       matlab.ui.container.Tab
        Image10                         matlab.ui.control.Image
        PatternEditTab                  matlab.ui.container.Tab
        PatternTypeDropDown             matlab.ui.control.DropDown
        PatternTypeDropDownLabel        matlab.ui.control.Label
        NewPatternDropDown              matlab.ui.control.DropDown
        NewPatternDropDownLabel         matlab.ui.control.Label
        Image11                         matlab.ui.control.Image
        PatternEditTab_2                matlab.ui.container.Tab
        Image49                         matlab.ui.control.Image
        Image11_2                       matlab.ui.control.Image
        PatternEditTab_3                matlab.ui.container.Tab
        Image49_3                       matlab.ui.control.Image
        Image49_2                       matlab.ui.control.Image
        Image11_3                       matlab.ui.control.Image
        ActionMapTab                    matlab.ui.container.Tab
        Image12                         matlab.ui.control.Image
        ExerciseTab                     matlab.ui.container.Tab
        Image13                         matlab.ui.control.Image
        EMGTab                          matlab.ui.container.Tab
        EMGSettingsTabGroup             matlab.ui.container.TabGroup
        BasicTab                        matlab.ui.container.Tab
        Image14                         matlab.ui.control.Image
        ControlTab                      matlab.ui.container.Tab
        EMGToggleCommandPanel           matlab.ui.container.Panel
        ToggleChannelDropDown           matlab.ui.control.DropDown
        ToggleChannelDropDownLabel      matlab.ui.control.Label
        StandardEMGPanel                matlab.ui.container.Panel
        LockTwitchChannelDropDown       matlab.ui.control.DropDown
        LockTwitchChannelDropDownLabel  matlab.ui.control.Label
        ProportionalChannelDropDown     matlab.ui.control.DropDown
        ProportionalChannelDropDownLabel  matlab.ui.control.Label
        UnLockDoubletChannelDropDown    matlab.ui.control.DropDown
        UnLockDoubletChannelDropDownLabel  matlab.ui.control.Label
        RawTab                          matlab.ui.container.Tab
        Image15                         matlab.ui.control.Image
        AutoTab                         matlab.ui.container.Tab
        Image19                         matlab.ui.control.Image
        Image18                         matlab.ui.control.Image
        Image17                         matlab.ui.control.Image
        Image16                         matlab.ui.control.Image
        AdjustTab                       matlab.ui.container.Tab
        ScalePanel                      matlab.ui.container.Panel
        UpdateButton_4                  matlab.ui.control.Button
        Poffset                         matlab.ui.control.NumericEditField
        PoffsetLabel                    matlab.ui.control.Label
        Pgain                           matlab.ui.control.NumericEditField
        PgainLabel                      matlab.ui.control.Label
        EMGWFL2Panel                    matlab.ui.container.Panel
        UpdateButton_3                  matlab.ui.control.Button
        WFLgain_2                       matlab.ui.control.NumericEditField
        WFLgainLabel_2                  matlab.ui.control.Label
        Depth_2                         matlab.ui.control.NumericEditField
        DepthLabel_2                    matlab.ui.control.Label
        HWgain_2                        matlab.ui.control.NumericEditField
        HWgainLabel_2                   matlab.ui.control.Label
        EMGWFL1Panel                    matlab.ui.container.Panel
        UpdateButton_2                  matlab.ui.control.Button
        WFLgain                         matlab.ui.control.NumericEditField
        WFLgainLabel                    matlab.ui.control.Label
        Depth                           matlab.ui.control.NumericEditField
        DepthLabel                      matlab.ui.control.Label
        HWgain                          matlab.ui.control.NumericEditField
        HWgainLabel                     matlab.ui.control.Label
        ViewEMGButton_2                 matlab.ui.control.StateButton
        Image21                         matlab.ui.control.Image
        LogicTab                        matlab.ui.container.Tab
        ViewEMGButton                   matlab.ui.control.StateButton
        TwitchParametersPanel           matlab.ui.container.Panel
        Quiet                           matlab.ui.control.NumericEditField
        QuietLabel                      matlab.ui.control.Label
        D5high                          matlab.ui.control.NumericEditField
        D5highLabel                     matlab.ui.control.Label
        DisableDouble                   matlab.ui.control.Button
        EnableDoublet                   matlab.ui.control.Button
        Image22                         matlab.ui.control.Image
        Practice1Tab                    matlab.ui.container.Tab
        UnlockButton                    matlab.ui.control.Button
        LockOptionsPanel_2              matlab.ui.container.Panel
        UnLockDoubletChanDropDown_2     matlab.ui.control.DropDown
        UnLockDoubletChanDropDown_2Label  matlab.ui.control.Label
        LockEMGChanDropDown_2           matlab.ui.control.DropDown
        LockEMGChanDropDown_2Label      matlab.ui.control.Label
        HoldPeriodsEditField_2          matlab.ui.control.NumericEditField
        HoldPeriodsEditField_2Label     matlab.ui.control.Label
        HoldEMGCheckBox_2               matlab.ui.control.CheckBox
        HoldOnlyCheckBox_2              matlab.ui.control.CheckBox
        Image47                         matlab.ui.control.Image
        Practice2Tab                    matlab.ui.container.Tab
        IncludeStimulationDropDown      matlab.ui.control.DropDown
        IncludeStimulationDropDownLabel  matlab.ui.control.Label
        TwitchParametersPanel_2         matlab.ui.container.Panel
        Quiet_2                         matlab.ui.control.NumericEditField
        QuietLabel_2                    matlab.ui.control.Label
        D5high_2                        matlab.ui.control.NumericEditField
        D5highLabel_2                   matlab.ui.control.Label
        DisableDouble_2                 matlab.ui.control.Button
        EnableDoublet_2                 matlab.ui.control.Button
        UnlockButton_2                  matlab.ui.control.Button
        Image48                         matlab.ui.control.Image
        AccTab                          matlab.ui.container.Tab
        Image23                         matlab.ui.control.Image
        DataLogTab                      matlab.ui.container.Tab
        Image35                         matlab.ui.control.Image
        PhoneAppTab                     matlab.ui.container.Tab
        Image46                         matlab.ui.control.Image
        DiagnosticTab                   matlab.ui.container.Tab
        TabGroup6                       matlab.ui.container.TabGroup
        Step1Tab                        matlab.ui.container.Tab
        steplabel                       matlab.ui.control.Label
        ContinueButton                  matlab.ui.control.Button
        diagnostics1b                   matlab.ui.control.CheckBox
        diagnostics1a                   matlab.ui.control.CheckBox
        Step2Tab                        matlab.ui.container.Tab
        steplabel_2                     matlab.ui.control.Label
        diagnostics1a_5                 matlab.ui.control.CheckBox
        diagnostics1a_4                 matlab.ui.control.CheckBox
        diagnostics1a_3                 matlab.ui.control.CheckBox
        diagnostics1a_2                 matlab.ui.control.CheckBox
        Step3Tab                        matlab.ui.container.Tab
        RunnetscannerLabel              matlab.ui.control.Label
        NetscanButton                   matlab.ui.control.Button
        diagnostics1a_8                 matlab.ui.control.CheckBox
        diagnostics1a_7                 matlab.ui.control.CheckBox
        diagnostics1a_6                 matlab.ui.control.CheckBox
        steplabel_3                     matlab.ui.control.Label
        Step4Tab                        matlab.ui.container.Tab
        SpecificobjectdictionarylocationsLabel  matlab.ui.control.Label
        ODButton                        matlab.ui.control.Button
        CopymemorytofileLabel           matlab.ui.control.Label
        MemoryButton                    matlab.ui.control.Button
        Read1F57parametersLabel         matlab.ui.control.Label
        F57Button                       matlab.ui.control.Button
        Read1F53parametersLabel         matlab.ui.control.Label
        F53Button                       matlab.ui.control.Button
        steplabel_4                     matlab.ui.control.Label
        F53Tab                          matlab.ui.container.Tab
        Image25                         matlab.ui.control.Image
        F57Tab                          matlab.ui.container.Tab
        Image26                         matlab.ui.control.Image
        ODTestTab                       matlab.ui.container.Tab
        UsereadonlyversionLabel         matlab.ui.control.Label
        Image27                         matlab.ui.control.Image
        NetscannerTab                   matlab.ui.container.Tab
        Image44_2                       matlab.ui.control.Image
        MemoryTab                       matlab.ui.container.Tab
        ReadPowerModuleandRemoteModuleMemoryLocationstoFileLabel  matlab.ui.control.Label
        Image36_2                       matlab.ui.control.Image
        TrunkTab                        matlab.ui.container.Tab
        TabGroup9                       matlab.ui.container.TabGroup
        SitTab                          matlab.ui.container.Tab
        CoughTab                        matlab.ui.container.Tab
        TransferTab                     matlab.ui.container.Tab
        TechnicianTab                   matlab.ui.container.Tab
        TabGroup7                       matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        TechMain                        matlab.ui.control.Label
        RadioTab_4                      matlab.ui.container.Tab
        Image34                         matlab.ui.control.Image
        Image33                         matlab.ui.control.Image
        Image32                         matlab.ui.control.Image
        NetScannerTab                   matlab.ui.container.Tab
        Image44_3                       matlab.ui.control.Image
        ScriptEditTab                   matlab.ui.container.Tab
        Image42                         matlab.ui.control.Image
        SchedulerTab                    matlab.ui.container.Tab
        Image43                         matlab.ui.control.Image
        PDOTab                          matlab.ui.container.Tab
        Image31                         matlab.ui.control.Image
        Image30                         matlab.ui.control.Image
        Image29                         matlab.ui.control.Image
        Image28                         matlab.ui.control.Image
        RemoteModTab                    matlab.ui.container.Tab
        Image41                         matlab.ui.control.Image
        TabGroup8                       matlab.ui.container.TabGroup
        EMGTab_2                        matlab.ui.container.Tab
        Image40                         matlab.ui.control.Image
        StimTab                         matlab.ui.container.Tab
        Image39                         matlab.ui.control.Image
        Image38                         matlab.ui.control.Image
        MemoryRestoreTab                matlab.ui.container.Tab
        Image37                         matlab.ui.control.Image
        Image36_3                       matlab.ui.control.Image
        HardwareTab_2                   matlab.ui.container.Tab
        TabGroup10                      matlab.ui.container.TabGroup
        ExternalTab                     matlab.ui.container.Tab
        TabGroup11                      matlab.ui.container.TabGroup
        RadioTab_5                      matlab.ui.container.Tab
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        TunerTab                        matlab.ui.container.Tab
        ChargingTab                     matlab.ui.container.Tab
        Image7                          matlab.ui.control.Image
        InternalTab                     matlab.ui.container.Tab
        HardwareTabGroup                matlab.ui.container.TabGroup
        ModulesTab                      matlab.ui.container.Tab
        Image3                          matlab.ui.control.Image
        ChannelsTab                     matlab.ui.container.Tab
        Image4                          matlab.ui.control.Image
        ConfigurationTab                matlab.ui.container.Tab
        TabGroup                        matlab.ui.container.TabGroup
        SyncTimingTab                   matlab.ui.container.Tab
        Image6                          matlab.ui.control.Image
        Image5                          matlab.ui.control.Image
        RemoteModuleConfigurationLabel  matlab.ui.control.Label
        OtherTab                        matlab.ui.container.Tab
    end

    
    properties (Access = private)
         LockDisable=[];
         templockdisable=0; % when I disable, I want to save the old value
    end
    
    methods (Access = private)
        
        function val = LockTable2Dec(~,LDtable)
            val=bin2dec(num2str(flip(LDtable{1:6,1}'))); % use only six bits
        end

        function val = Dec2LockTable(~,numDec)
            val=table(); % This gives me the bits.  Now need to create a table
            val{[1:6],1}=logical(bitget(uint8(numDec),[1:6]))';
        end
        
        function controlOptionsDisplay(app)
% Hand
            if app.HandCheckBox.Value
                app.PatternChangeOptionsPanel.Enable='on';
                app.HandPanel.Enable='on';
                
     % Lock           
             if app.HandControlMethodDropDown.Value==1 % EMG normal
                 app.EMGTab.Title='EMG';
                 app.LockOptionsPanel.Enable='on';
                 app.StandardEMGPanel.Enable='on';
                 app.EMGToggleCommandPanel.Enable='off';
                 app.LockOptionsPanel_2.Enable='on';
                 app.UnlockButton.Enable='on';
                 app.UnlockButton_2.Enable='on';
             else
                 app.StandardEMGPanel.Enable='off';
                 app.LockOptionsPanel_2.Enable='off';
                 app.LockOptionsPanel.Enable='off';
                 app.UnlockButton.Enable='off';
                 app.UnlockButton_2.Enable='off';
        
                 if app.HandControlMethodDropDown.Value==3 % no EMG
                     %app.StandardEMGPanel.Enable='off';
                     app.EMGTab.Title=''; % no EMG used
                     app.EMGSettingsTabGroup.Visible='off';
                 else % EMG toggle
                     %app.StandardEMGPanel.Enable='on';
                     app.EMGTab.Title='EMG';
                     app.EMGSettingsTabGroup.Visible='on';
                     app.EMGToggleCommandPanel.Enable='on';
                 end
             end
      % Hysteresis          
                if app.HysteresisCheckBox.Value
                    app.HysteresisPanel.Enable='on';
                else
                    app.HysteresisPanel.Enable='off';
                end
            else
                app.PatternChangeOptionsPanel.Enable='off';
                app.LockOptionsPanel.Enable='off';
                app.HandPanel.Enable='off';
                app.HysteresisPanel.Enable='off';
            end
            
% Triceps            
            if app.TricepsCheckBox.Value
                app.TricepsControlPanel.Enable='on';
                app.AccTab.Title='Acc';
                app.Image23.Visible='on';
                if strcmp(app.TricepsControlMethodDropDown.Value,'Accelerometer')
                   app.RampPanel.Enable='off'; 
                else 
                    app.RampPanel.Enable='on'; 
                end
            else
                app.TricepsControlPanel.Enable='off';
                app.AccTab.Title='';
                app.Image23.Visible='off';
            end
            
% Trunk            
            if app.TrunkCheckBox.Value
                app.TrunkOptionsPanel.Enable='on';
                app.TabGroup9.Visible='on';
                app.TrunkTab.Title='Trunk';
            else
                app.TrunkOptionsPanel.Enable='off';
                app.TabGroup9.Visible='off';
                app.TrunkTab.Title='';
            end
 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.LockDisable=table([false;false;false;false;false;false]); % bit 0 is disable all, bits 1-5 for patterns 1-5
            app.LockDisableTable.Data=app.LockDisable;
            % convert bits to integer
            LD=app.LockTable2Dec(app.LockDisable);
            app.DisableLocks.Value=LD; % this is now double
            app.templockdisable=app.DisableLocks.Value; %use the default to start with in case the enable button is pressed.          
            app.HandControlMethodDropDown.ItemsData=[1,2,3]; % to make comparing easier
            app.HandControlMethodDropDown.Value=1;
            app.controlOptionsDisplay(); % this has to be the last line of startup
        end

        % Value changed function: HandCheckBox
        function HandCheckBoxValueChanged(app, event)
             app.controlOptionsDisplay();
        end

        % Value changed function: TricepsCheckBox
        function TricepsCheckBoxValueChanged(app, event)
            app.controlOptionsDisplay();
        end

        % Value changed function: TrunkCheckBox
        function TrunkCheckBoxValueChanged(app, event)
           app.controlOptionsDisplay();
        end

        % Value changed function: HysteresisCheckBox
        function HysteresisCheckBoxValueChanged(app, event)

            app.controlOptionsDisplay();
        end

        % Value changed function: HandControlMethodDropDown
        function HandControlMethodDropDownValueChanged(app, event)
            
            app.controlOptionsDisplay();
        end

        % Value changed function: TricepsControlMethodDropDown
        function TricepsControlMethodDropDownValueChanged(app, event)
            app.controlOptionsDisplay();
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.MainTabGroup = uitabgroup(app.UIFigure);
            app.ParticipantTab = uitab(app.MainTabGroup);
            app.ParticipantTab.Title = 'Participant';
            app.NewParticipantButton = uibutton(app.ParticipantTab, 'push');
            app.NewParticipantButton.Text = 'New Participant';
            app.ParticipantDropDownLabel = uilabel(app.ParticipantTab);
            app.ParticipantDropDownLabel.Text = 'Participant';
            app.ParticipantDropDown = uidropdown(app.ParticipantTab);
            app.ParticipantDropDown.Items = {'choose', 'CLE125', 'CLE068', 'CLE366'};
            app.ParticipantDropDown.Value = 'choose';
            app.HardwareTab = uitab(app.MainTabGroup);
            app.HardwareTab.Title = 'Hardware';
            app.ChargingPanel = uipanel(app.HardwareTab);
            app.ChargingPanel.Title = 'Charging';
            app.AgreaterdepthrequiresmorepowertoreachthePMLabel = uilabel(app.ChargingPanel);
            app.AgreaterdepthrequiresmorepowertoreachthePMLabel.Text = 'A greater depth requires more power to reach the PM ';
            app.AgreaterdepthrequiresmorepowertoreachthePMLabel_2 = uilabel(app.ChargingPanel);
            app.AgreaterdepthrequiresmorepowertoreachthePMLabel_2.Text = {'Aggressive charging is faster, but can '; 'be more sensitive to the coil location and cooling'};
            app.PMLocationSwitchLabel = uilabel(app.ChargingPanel);
            app.PMLocationSwitchLabel.Text = 'PM Location';
            app.PMLocationSwitch = uiswitch(app.ChargingPanel, 'slider');
            app.PMLocationSwitch.Items = {'Shallow', 'Deep'};
            app.PMLocationSwitch.Value = 'Shallow';
            app.ChargingIntensitySwitchLabel = uilabel(app.ChargingPanel);
            app.ChargingIntensitySwitchLabel.Text = 'Charging Intensity';
            app.ChargingIntensitySwitch = uiswitch(app.ChargingPanel, 'slider');
            app.ChargingIntensitySwitch.Items = {'Normal', 'Aggressive'};
            app.ChargingIntensitySwitch.Value = 'Normal';
            app.ModulesPanel = uipanel(app.HardwareTab);
            app.ModulesPanel.Title = 'Modules';
            app.Image50 = uiimage(app.ModulesPanel);
            app.Image50.ImageSource = 'Module locations Right with boxes.png';
            app.EditField = uieditfield(app.ModulesPanel, 'numeric');
            app.EditField.ValueDisplayFormat = '%1.0g';
            app.EditField_2 = uieditfield(app.ModulesPanel, 'numeric');
            app.EditField_2.ValueDisplayFormat = '%1.0g';
            app.EditField_3 = uieditfield(app.ModulesPanel, 'numeric');
            app.EditField_3.ValueDisplayFormat = '%1.0g';
            app.EditField_3.Value = 3;
            app.EditField_4 = uieditfield(app.ModulesPanel, 'numeric');
            app.EditField_4.ValueDisplayFormat = '%1.0g';
            app.EditField_4.Value = 1;
            app.EditField_5 = uieditfield(app.ModulesPanel, 'numeric');
            app.EditField_5.ValueDisplayFormat = '%1.0g';
            app.EditField_5.Value = 2;
            app.EditField_6 = uieditfield(app.ModulesPanel, 'numeric');
            app.EditField_6.ValueDisplayFormat = '%1.0g';
            app.EditField_7 = uieditfield(app.ModulesPanel, 'numeric');
            app.EditField_7.ValueDisplayFormat = '%1.0g';
            app.EditField_7.Value = 9;
            app.RightLabel = uilabel(app.ModulesPanel);
            app.RightLabel.Text = 'Right';
            app.LeftLabel_2 = uilabel(app.ModulesPanel);
            app.LeftLabel_2.Text = 'Left';
            app.WewouldhavesimilardisplayforleftarmandPMLabel = uilabel(app.HardwareTab);
            app.WewouldhavesimilardisplayforleftarmandPMLabel.Text = 'We would have similar display for left arm and PM';
            app.OptionsTab = uitab(app.MainTabGroup);
            app.OptionsTab.Title = 'Options';
            app.NetworkVoltageEditFieldLabel = uilabel(app.OptionsTab);
            app.NetworkVoltageEditFieldLabel.Text = 'Network Voltage';
            app.NetworkVoltageEditField = uieditfield(app.OptionsTab, 'numeric');
            app.IncludedMuscleGroupsPanel = uipanel(app.OptionsTab);
            app.IncludedMuscleGroupsPanel.Title = 'Included Muscle Groups';
            app.HandCheckBox = uicheckbox(app.IncludedMuscleGroupsPanel);
            app.HandCheckBox.ValueChangedFcn = createCallbackFcn(app, @HandCheckBoxValueChanged, true);
            app.HandCheckBox.Text = 'Hand';
            app.HandCheckBox.Value = true;
            app.TricepsCheckBox = uicheckbox(app.IncludedMuscleGroupsPanel);
            app.TricepsCheckBox.ValueChangedFcn = createCallbackFcn(app, @TricepsCheckBoxValueChanged, true);
            app.TricepsCheckBox.Text = 'Triceps';
            app.TricepsCheckBox.Value = true;
            app.TrunkCheckBox = uicheckbox(app.IncludedMuscleGroupsPanel);
            app.TrunkCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrunkCheckBoxValueChanged, true);
            app.TrunkCheckBox.Text = 'Trunk';
            app.TrunkCheckBox.Value = true;
            app.HandPanel = uipanel(app.OptionsTab);
            app.HandPanel.Title = 'Hand';
            app.HandControlMethodDropDownLabel = uilabel(app.HandPanel);
            app.HandControlMethodDropDownLabel.Text = 'Hand Control Method';
            app.HandControlMethodDropDown = uidropdown(app.HandPanel);
            app.HandControlMethodDropDown.Items = {'Proportional EMG', 'Ramp Toggle EMG', 'Ramp Toggle Button Only'};
            app.HandControlMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @HandControlMethodDropDownValueChanged, true);
            app.HandControlMethodDropDown.Value = 'Proportional EMG';
            app.HysteresisCheckBox = uicheckbox(app.HandPanel);
            app.HysteresisCheckBox.ValueChangedFcn = createCallbackFcn(app, @HysteresisCheckBoxValueChanged, true);
            app.HysteresisCheckBox.Text = 'Hysteresis';
            app.LockOptionsPanel = uipanel(app.OptionsTab);
            app.LockOptionsPanel.Title = 'Lock Options';
            app.HoldOnlyCheckBox = uicheckbox(app.LockOptionsPanel);
            app.HoldOnlyCheckBox.Text = 'Hold Only';
            app.HoldEMGCheckBox = uicheckbox(app.LockOptionsPanel);
            app.HoldEMGCheckBox.Text = 'Hold + EMG';
            app.HoldPeriodsEditFieldLabel = uilabel(app.LockOptionsPanel);
            app.HoldPeriodsEditFieldLabel.Text = 'Hold Period, s';
            app.HoldPeriodsEditField = uieditfield(app.LockOptionsPanel, 'numeric');
            app.LockEMGChanDropDownLabel = uilabel(app.LockOptionsPanel);
            app.LockEMGChanDropDownLabel.Text = 'Lock EMG Chan';
            app.LockEMGChanDropDown = uidropdown(app.LockOptionsPanel);
            app.LockEMGChanDropDown.Items = {'EMG1', 'EMG2'};
            app.LockEMGChanDropDown.Value = 'EMG1';
            app.UnLockDoubletChanDropDownLabel = uilabel(app.LockOptionsPanel);
            app.UnLockDoubletChanDropDownLabel.Text = 'UnLock Doublet Chan';
            app.UnLockDoubletChanDropDown = uidropdown(app.LockOptionsPanel);
            app.UnLockDoubletChanDropDown.Items = {'EMG1', 'EMG2'};
            app.UnLockDoubletChanDropDown.Value = 'EMG1';
            app.LockDisablePanel = uipanel(app.LockOptionsPanel);
            app.LockDisablePanel.Tooltip = {'Select which patterns should have locks disabled,'};
            app.LockDisablePanel.Title = 'LockDisable';
            app.LockDisableTable = uitable(app.LockDisablePanel);
            app.LockDisableTable.ColumnName = '';
            app.LockDisableTable.RowName = {'All'; 'Pat 1'; 'Pat 2'; 'Pat 3'; 'Pat 4'; 'Pat 5'};
            app.LockDisableTable.ColumnEditable = true;
            app.DisableLocksLabel = uilabel(app.LockDisablePanel);
            app.DisableLocksLabel.Text = 'DisableLocks';
            app.DisableLocks = uieditfield(app.LockDisablePanel, 'numeric');
            app.DisableLocks.Tooltip = {'Bit 0=all, Bits 1-5 for Hand patterns 1-5'};
            app.PatternChangeOptionsPanel = uipanel(app.OptionsTab);
            app.PatternChangeOptionsPanel.Title = 'Pattern Change Options';
            app.PatternChangeDropDownLabel = uilabel(app.PatternChangeOptionsPanel);
            app.PatternChangeDropDownLabel.Text = 'Pattern Change';
            app.PatternChangeDropDown = uidropdown(app.PatternChangeOptionsPanel);
            app.PatternChangeDropDown.Items = {'Buttons Only', 'Long EMG Twitch'};
            app.PatternChangeDropDown.Value = 'Buttons Only';
            app.CycleThrough0CheckBox = uicheckbox(app.PatternChangeOptionsPanel);
            app.CycleThrough0CheckBox.Text = 'Cycle Through 0';
            app.FavoritesCheckBox = uicheckbox(app.PatternChangeOptionsPanel);
            app.FavoritesCheckBox.Text = 'Favorites';
            app.FavoritesNumEditFieldLabel = uilabel(app.PatternChangeOptionsPanel);
            app.FavoritesNumEditFieldLabel.Text = 'Favorites Num';
            app.FavoritesNumEditField = uieditfield(app.PatternChangeOptionsPanel, 'numeric');
            app.TricepsControlPanel = uipanel(app.OptionsTab);
            app.TricepsControlPanel.Title = 'Triceps Control';
            app.TricepsControlMethodDropDownLabel = uilabel(app.TricepsControlPanel);
            app.TricepsControlMethodDropDownLabel.Text = 'Triceps Control Method';
            app.TricepsControlMethodDropDown = uidropdown(app.TricepsControlPanel);
            app.TricepsControlMethodDropDown.Items = {'Accelerometer', 'Button Ramp'};
            app.TricepsControlMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @TricepsControlMethodDropDownValueChanged, true);
            app.TricepsControlMethodDropDown.Value = 'Accelerometer';
            app.RampPanel = uipanel(app.TricepsControlPanel);
            app.RampPanel.Title = 'Ramp';
            app.DelayEditFieldLabel = uilabel(app.RampPanel);
            app.DelayEditFieldLabel.Text = 'Delay';
            app.DelayEditField = uieditfield(app.RampPanel, 'numeric');
            app.RampUpEditFieldLabel = uilabel(app.RampPanel);
            app.RampUpEditFieldLabel.Text = 'Ramp Up';
            app.RampUpEditField = uieditfield(app.RampPanel, 'numeric');
            app.RampUpEditField.Value = 26;
            app.RampDownEditFieldLabel = uilabel(app.RampPanel);
            app.RampDownEditFieldLabel.Text = 'Ramp Down';
            app.RampDownEditField = uieditfield(app.RampPanel, 'numeric');
            app.RampDownEditField.Value = 13;
            app.TrunkOptionsPanel = uipanel(app.OptionsTab);
            app.TrunkOptionsPanel.Title = 'Trunk Options';
            app.HysteresisPanel = uipanel(app.OptionsTab);
            app.HysteresisPanel.Title = 'Hysteresis';
            app.Image45 = uiimage(app.HysteresisPanel);
            app.Image45.ImageSource = 'SB_079.png';
            app.PatternEnableLabel = uilabel(app.HysteresisPanel);
            app.PatternEnableLabel.Text = 'Pattern   Enable';
            app.HoldsEditFieldLabel = uilabel(app.HysteresisPanel);
            app.HoldsEditFieldLabel.Text = 'Hold,s';
            app.HoldsEditField = uieditfield(app.HysteresisPanel, 'numeric');
            app.SSminimumEditFieldLabel = uilabel(app.HysteresisPanel);
            app.SSminimumEditFieldLabel.Text = 'SS minimum';
            app.SSminimumEditField = uieditfield(app.HysteresisPanel, 'numeric');
            app.StatusTab = uitab(app.MainTabGroup);
            app.StatusTab.Title = 'Status';
            app.Image8 = uiimage(app.StatusTab);
            app.Image8.ImageSource = 'SB_006.png';
            app.ProfileTab = uitab(app.MainTabGroup);
            app.ProfileTab.Title = 'Profile';
            app.Image9 = uiimage(app.ProfileTab);
            app.Image9.ImageSource = 'SB_110.png';
            app.PatternsTab = uitab(app.MainTabGroup);
            app.PatternsTab.Title = 'Patterns';
            app.TabGroup2 = uitabgroup(app.PatternsTab);
            app.GroupsTab = uitab(app.TabGroup2);
            app.GroupsTab.Title = 'Groups';
            app.Image10 = uiimage(app.GroupsTab);
            app.Image10.ImageSource = 'SB_010.png';
            app.PatternEditTab = uitab(app.TabGroup2);
            app.PatternEditTab.Title = 'Pattern Edit';
            app.Image11 = uiimage(app.PatternEditTab);
            app.Image11.ImageSource = 'SB_108.png';
            app.NewPatternDropDownLabel = uilabel(app.PatternEditTab);
            app.NewPatternDropDownLabel.Text = 'New Pattern';
            app.NewPatternDropDown = uidropdown(app.PatternEditTab);
            app.NewPatternDropDown.Items = {'Lateral', 'Palmar', 'Triceps', 'Blank', 'Duplicate'};
            app.NewPatternDropDown.Value = 'Lateral';
            app.PatternTypeDropDownLabel = uilabel(app.PatternEditTab);
            app.PatternTypeDropDownLabel.Text = 'Pattern Type';
            app.PatternTypeDropDown = uidropdown(app.PatternEditTab);
            app.PatternTypeDropDown.Items = {'Hand', 'Triceps', 'Trunk', 'Other'};
            app.PatternTypeDropDown.Value = 'Hand';
            app.PatternEditTab_2 = uitab(app.TabGroup2);
            app.PatternEditTab_2.Title = 'Pattern Edit';
            app.Image11_2 = uiimage(app.PatternEditTab_2);
            app.Image11_2.ImageSource = 'SB_108.png';
            app.Image49 = uiimage(app.PatternEditTab_2);
            app.Image49.ImageSource = 'SB_094.png';
            app.PatternEditTab_3 = uitab(app.TabGroup2);
            app.PatternEditTab_3.Title = 'Pattern Edit';
            app.Image11_3 = uiimage(app.PatternEditTab_3);
            app.Image11_3.ImageSource = 'SB_108.png';
            app.Image49_2 = uiimage(app.PatternEditTab_3);
            app.Image49_2.ImageSource = 'SB_097.png';
            app.Image49_3 = uiimage(app.PatternEditTab_3);
            app.Image49_3.ImageSource = 'SB_109.png';
            app.ActionMapTab = uitab(app.TabGroup2);
            app.ActionMapTab.Title = 'Action Map';
            app.Image12 = uiimage(app.ActionMapTab);
            app.Image12.ImageSource = 'SB_086.png';
            app.ExerciseTab = uitab(app.MainTabGroup);
            app.ExerciseTab.Title = 'Exercise';
            app.Image13 = uiimage(app.ExerciseTab);
            app.Image13.ImageSource = 'SB_013.png';
            app.EMGTab = uitab(app.MainTabGroup);
            app.EMGTab.Title = 'EMG';
            app.EMGSettingsTabGroup = uitabgroup(app.EMGTab);
            app.BasicTab = uitab(app.EMGSettingsTabGroup);
            app.BasicTab.Title = 'Basic';
            app.Image14 = uiimage(app.BasicTab);
            app.Image14.ImageSource = 'SB_014.png';
            app.ControlTab = uitab(app.EMGSettingsTabGroup);
            app.ControlTab.Title = 'Control';
            app.StandardEMGPanel = uipanel(app.ControlTab);
            app.StandardEMGPanel.Title = 'Standard EMG';
            app.UnLockDoubletChannelDropDownLabel = uilabel(app.StandardEMGPanel);
            app.UnLockDoubletChannelDropDownLabel.Text = 'UnLock Doublet Channel';
            app.UnLockDoubletChannelDropDown = uidropdown(app.StandardEMGPanel);
            app.UnLockDoubletChannelDropDown.Items = {'EMG1', 'EMG2'};
            app.UnLockDoubletChannelDropDown.Value = 'EMG1';
            app.ProportionalChannelDropDownLabel = uilabel(app.StandardEMGPanel);
            app.ProportionalChannelDropDownLabel.Text = 'Proportional Channel';
            app.ProportionalChannelDropDown = uidropdown(app.StandardEMGPanel);
            app.ProportionalChannelDropDown.Items = {'EMG1', 'EMG2'};
            app.ProportionalChannelDropDown.Value = 'EMG1';
            app.LockTwitchChannelDropDownLabel = uilabel(app.StandardEMGPanel);
            app.LockTwitchChannelDropDownLabel.Text = 'Lock Twitch Channel';
            app.LockTwitchChannelDropDown = uidropdown(app.StandardEMGPanel);
            app.LockTwitchChannelDropDown.Items = {'EMG1', 'EMG2'};
            app.LockTwitchChannelDropDown.Value = 'EMG1';
            app.EMGToggleCommandPanel = uipanel(app.ControlTab);
            app.EMGToggleCommandPanel.Title = 'EMG Toggle Command';
            app.ToggleChannelDropDownLabel = uilabel(app.EMGToggleCommandPanel);
            app.ToggleChannelDropDownLabel.Text = 'Toggle Channel';
            app.ToggleChannelDropDown = uidropdown(app.EMGToggleCommandPanel);
            app.ToggleChannelDropDown.Items = {'EMG1', 'EMG2'};
            app.ToggleChannelDropDown.Value = 'EMG1';
            app.RawTab = uitab(app.EMGSettingsTabGroup);
            app.RawTab.Title = 'Raw';
            app.Image15 = uiimage(app.RawTab);
            app.Image15.ImageSource = 'SB_018.png';
            app.AutoTab = uitab(app.EMGSettingsTabGroup);
            app.AutoTab.Title = 'Auto';
            app.Image16 = uiimage(app.AutoTab);
            app.Image16.ImageSource = 'SB_054.png';
            app.Image17 = uiimage(app.AutoTab);
            app.Image17.ImageSource = 'SB_055.png';
            app.Image18 = uiimage(app.AutoTab);
            app.Image18.ImageSource = 'SB_056.png';
            app.Image19 = uiimage(app.AutoTab);
            app.Image19.ImageSource = 'SB_057.png';
            app.AdjustTab = uitab(app.EMGSettingsTabGroup);
            app.AdjustTab.Title = 'Adjust';
            app.Image21 = uiimage(app.AdjustTab);
            app.Image21.ImageSource = 'SB_024.png';
            app.ViewEMGButton_2 = uibutton(app.AdjustTab, 'state');
            app.ViewEMGButton_2.Text = 'View EMG';
            app.EMGWFL1Panel = uipanel(app.AdjustTab);
            app.EMGWFL1Panel.Title = 'EMG WFL 1';
            app.HWgainLabel = uilabel(app.EMGWFL1Panel);
            app.HWgainLabel.Text = 'HWgain';
            app.HWgain = uieditfield(app.EMGWFL1Panel, 'numeric');
            app.HWgain.Value = 1;
            app.DepthLabel = uilabel(app.EMGWFL1Panel);
            app.DepthLabel.Text = 'Depth';
            app.Depth = uieditfield(app.EMGWFL1Panel, 'numeric');
            app.Depth.Value = 1;
            app.WFLgainLabel = uilabel(app.EMGWFL1Panel);
            app.WFLgainLabel.Text = 'WFLgain';
            app.WFLgain = uieditfield(app.EMGWFL1Panel, 'numeric');
            app.WFLgain.Value = 1;
            app.UpdateButton_2 = uibutton(app.EMGWFL1Panel, 'push');
            app.UpdateButton_2.Tooltip = {'Send WFL1 parameters'};
            app.UpdateButton_2.Text = '1';
            app.EMGWFL2Panel = uipanel(app.AdjustTab);
            app.EMGWFL2Panel.Title = 'EMG WFL 2';
            app.HWgainLabel_2 = uilabel(app.EMGWFL2Panel);
            app.HWgainLabel_2.Text = 'HWgain';
            app.HWgain_2 = uieditfield(app.EMGWFL2Panel, 'numeric');
            app.HWgain_2.Value = 1;
            app.DepthLabel_2 = uilabel(app.EMGWFL2Panel);
            app.DepthLabel_2.Text = 'Depth';
            app.Depth_2 = uieditfield(app.EMGWFL2Panel, 'numeric');
            app.Depth_2.Value = 1;
            app.WFLgainLabel_2 = uilabel(app.EMGWFL2Panel);
            app.WFLgainLabel_2.Text = 'WFLgain';
            app.WFLgain_2 = uieditfield(app.EMGWFL2Panel, 'numeric');
            app.WFLgain_2.Value = 1;
            app.UpdateButton_3 = uibutton(app.EMGWFL2Panel, 'push');
            app.UpdateButton_3.Tooltip = {'Send WFL2 parameters'};
            app.UpdateButton_3.Text = '2';
            app.ScalePanel = uipanel(app.AdjustTab);
            app.ScalePanel.Title = 'Scale';
            app.PgainLabel = uilabel(app.ScalePanel);
            app.PgainLabel.Text = 'Pgain';
            app.Pgain = uieditfield(app.ScalePanel, 'numeric');
            app.Pgain.Value = 1;
            app.PoffsetLabel = uilabel(app.ScalePanel);
            app.PoffsetLabel.Text = 'Poffset';
            app.Poffset = uieditfield(app.ScalePanel, 'numeric');
            app.UpdateButton_4 = uibutton(app.ScalePanel, 'push');
            app.UpdateButton_4.Tooltip = {'Send proportional gain parameters'};
            app.UpdateButton_4.Text = '4';
            app.LogicTab = uitab(app.EMGSettingsTabGroup);
            app.LogicTab.Title = 'Logic';
            app.Image22 = uiimage(app.LogicTab);
            app.Image22.ImageSource = 'SB_026.png';
            app.TwitchParametersPanel = uipanel(app.LogicTab);
            app.TwitchParametersPanel.Title = 'Twitch Parameters 1';
            app.EnableDoublet = uibutton(app.TwitchParametersPanel, 'push');
            app.EnableDoublet.Text = {'Enable'; 'Doublet'};
            app.DisableDouble = uibutton(app.TwitchParametersPanel, 'push');
            app.DisableDouble.Text = {'Disable'; 'Doublet'};
            app.D5highLabel = uilabel(app.TwitchParametersPanel);
            app.D5highLabel.Text = 'D5high';
            app.D5high = uieditfield(app.TwitchParametersPanel, 'numeric');
            app.D5high.Value = 200;
            app.QuietLabel = uilabel(app.TwitchParametersPanel);
            app.QuietLabel.Text = 'Quiet';
            app.Quiet = uieditfield(app.TwitchParametersPanel, 'numeric');
            app.ViewEMGButton = uibutton(app.LogicTab, 'state');
            app.ViewEMGButton.Text = 'View EMG';
            app.Practice1Tab = uitab(app.EMGSettingsTabGroup);
            app.Practice1Tab.Title = 'Practice1';
            app.Image47 = uiimage(app.Practice1Tab);
            app.Image47.ImageSource = 'SB_103.png';
            app.LockOptionsPanel_2 = uipanel(app.Practice1Tab);
            app.LockOptionsPanel_2.Title = 'Lock Options';
            app.HoldOnlyCheckBox_2 = uicheckbox(app.LockOptionsPanel_2);
            app.HoldOnlyCheckBox_2.Text = 'Hold Only';
            app.HoldEMGCheckBox_2 = uicheckbox(app.LockOptionsPanel_2);
            app.HoldEMGCheckBox_2.Text = 'Hold + EMG';
            app.HoldPeriodsEditField_2Label = uilabel(app.LockOptionsPanel_2);
            app.HoldPeriodsEditField_2Label.Text = 'Hold Period, s';
            app.HoldPeriodsEditField_2 = uieditfield(app.LockOptionsPanel_2, 'numeric');
            app.LockEMGChanDropDown_2Label = uilabel(app.LockOptionsPanel_2);
            app.LockEMGChanDropDown_2Label.Text = 'Lock EMG Chan';
            app.LockEMGChanDropDown_2 = uidropdown(app.LockOptionsPanel_2);
            app.LockEMGChanDropDown_2.Items = {'EMG1', 'EMG2'};
            app.LockEMGChanDropDown_2.Value = 'EMG1';
            app.UnLockDoubletChanDropDown_2Label = uilabel(app.LockOptionsPanel_2);
            app.UnLockDoubletChanDropDown_2Label.Text = 'UnLock Doublet Chan';
            app.UnLockDoubletChanDropDown_2 = uidropdown(app.LockOptionsPanel_2);
            app.UnLockDoubletChanDropDown_2.Items = {'EMG1', 'EMG2'};
            app.UnLockDoubletChanDropDown_2.Value = 'EMG1';
            app.UnlockButton = uibutton(app.Practice1Tab, 'push');
            app.UnlockButton.Text = 'Unlock';
            app.Practice2Tab = uitab(app.EMGSettingsTabGroup);
            app.Practice2Tab.Title = 'Practice2';
            app.Image48 = uiimage(app.Practice2Tab);
            app.Image48.ImageSource = 'SB_107.png';
            app.UnlockButton_2 = uibutton(app.Practice2Tab, 'push');
            app.UnlockButton_2.Text = 'Unlock';
            app.TwitchParametersPanel_2 = uipanel(app.Practice2Tab);
            app.TwitchParametersPanel_2.Title = 'Twitch Parameters 1';
            app.EnableDoublet_2 = uibutton(app.TwitchParametersPanel_2, 'push');
            app.EnableDoublet_2.Text = {'Enable'; 'Doublet'};
            app.DisableDouble_2 = uibutton(app.TwitchParametersPanel_2, 'push');
            app.DisableDouble_2.Text = {'Disable'; 'Doublet'};
            app.D5highLabel_2 = uilabel(app.TwitchParametersPanel_2);
            app.D5highLabel_2.Text = 'D5high';
            app.D5high_2 = uieditfield(app.TwitchParametersPanel_2, 'numeric');
            app.D5high_2.Value = 200;
            app.QuietLabel_2 = uilabel(app.TwitchParametersPanel_2);
            app.QuietLabel_2.Text = 'Quiet';
            app.Quiet_2 = uieditfield(app.TwitchParametersPanel_2, 'numeric');
            app.IncludeStimulationDropDownLabel = uilabel(app.Practice2Tab);
            app.IncludeStimulationDropDownLabel.Text = 'Include Stimulation';
            app.IncludeStimulationDropDown = uidropdown(app.Practice2Tab);
            app.IncludeStimulationDropDown.Items = {'None', 'Pattern 1', 'Pattern 2', 'Pattern 3', 'Pattern 4', 'Pattern 5'};
            app.IncludeStimulationDropDown.Value = 'None';
            app.AccTab = uitab(app.MainTabGroup);
            app.AccTab.Title = 'Acc';
            app.Image23 = uiimage(app.AccTab);
            app.Image23.ImageSource = 'SB_114.png';
            app.DataLogTab = uitab(app.MainTabGroup);
            app.DataLogTab.Title = 'DataLog';
            app.Image35 = uiimage(app.DataLogTab);
            app.Image35.ImageSource = 'SB_036.png';
            app.PhoneAppTab = uitab(app.MainTabGroup);
            app.PhoneAppTab.Title = 'PhoneApp';
            app.Image46 = uiimage(app.PhoneAppTab);
            app.Image46.ImageSource = 'SB_038.png';
            app.DiagnosticTab = uitab(app.MainTabGroup);
            app.DiagnosticTab.Title = 'Diagnostic';
            app.TabGroup6 = uitabgroup(app.DiagnosticTab);
            app.Step1Tab = uitab(app.TabGroup6);
            app.Step1Tab.Title = 'Step1';
            app.diagnostics1a = uicheckbox(app.Step1Tab);
            app.diagnostics1a.Text = 'Checking radio settings';
            app.diagnostics1b = uicheckbox(app.Step1Tab);
            app.diagnostics1b.Text = 'Clinical Tool communications with power module';
            app.ContinueButton = uibutton(app.Step1Tab, 'push');
            app.ContinueButton.Text = 'Continue';
            app.steplabel = uilabel(app.Step1Tab);
            app.steplabel.Text = 'Step 1 - Communications';
            app.Step2Tab = uitab(app.TabGroup6);
            app.Step2Tab.Title = 'Step2';
            app.diagnostics1a_2 = uicheckbox(app.Step2Tab);
            app.diagnostics1a_2.Text = 'Disable scripts';
            app.diagnostics1a_3 = uicheckbox(app.Step2Tab);
            app.diagnostics1a_3.Text = 'Reset 1F53';
            app.diagnostics1a_4 = uicheckbox(app.Step2Tab);
            app.diagnostics1a_4.Text = 'Enable Scripts';
            app.diagnostics1a_5 = uicheckbox(app.Step2Tab);
            app.diagnostics1a_5.Text = 'Run Startup Scripts';
            app.steplabel_2 = uilabel(app.Step2Tab);
            app.steplabel_2.Text = 'Step 2 - Scripts';
            app.Step3Tab = uitab(app.TabGroup6);
            app.Step3Tab.Title = 'Step3';
            app.steplabel_3 = uilabel(app.Step3Tab);
            app.steplabel_3.Text = 'Step 3 - Network and Remote Modules';
            app.diagnostics1a_6 = uicheckbox(app.Step3Tab);
            app.diagnostics1a_6.Text = 'Network On';
            app.diagnostics1a_7 = uicheckbox(app.Step3Tab);
            app.diagnostics1a_7.Text = 'Read stim modules status';
            app.diagnostics1a_8 = uicheckbox(app.Step3Tab);
            app.diagnostics1a_8.Text = 'Read BP module status';
            app.NetscanButton = uibutton(app.Step3Tab, 'push');
            app.NetscanButton.Text = 'Netscan';
            app.RunnetscannerLabel = uilabel(app.Step3Tab);
            app.RunnetscannerLabel.Text = 'Run net scanner';
            app.Step4Tab = uitab(app.TabGroup6);
            app.Step4Tab.Title = 'Step4';
            app.steplabel_4 = uilabel(app.Step4Tab);
            app.steplabel_4.Text = 'Step 4 - Parameters and Settings';
            app.F53Button = uibutton(app.Step4Tab, 'push');
            app.F53Button.Text = '1F53';
            app.Read1F53parametersLabel = uilabel(app.Step4Tab);
            app.Read1F53parametersLabel.Text = 'Read 1F53 parameters';
            app.F57Button = uibutton(app.Step4Tab, 'push');
            app.F57Button.Text = '1F57';
            app.Read1F57parametersLabel = uilabel(app.Step4Tab);
            app.Read1F57parametersLabel.Text = 'Read 1F57 parameters';
            app.MemoryButton = uibutton(app.Step4Tab, 'push');
            app.MemoryButton.Text = 'Memory';
            app.CopymemorytofileLabel = uilabel(app.Step4Tab);
            app.CopymemorytofileLabel.Text = 'Copy memory to file';
            app.ODButton = uibutton(app.Step4Tab, 'push');
            app.ODButton.Text = 'OD';
            app.SpecificobjectdictionarylocationsLabel = uilabel(app.Step4Tab);
            app.SpecificobjectdictionarylocationsLabel.Text = 'Specific object dictionary locations';
            app.F53Tab = uitab(app.TabGroup6);
            app.F53Tab.Title = '1F53';
            app.Image25 = uiimage(app.F53Tab);
            app.Image25.ImageSource = 'SB_043.png';
            app.F57Tab = uitab(app.TabGroup6);
            app.F57Tab.Title = '1F57';
            app.Image26 = uiimage(app.F57Tab);
            app.Image26.ImageSource = 'SB_035.png';
            app.ODTestTab = uitab(app.TabGroup6);
            app.ODTestTab.Title = 'ODTest';
            app.Image27 = uiimage(app.ODTestTab);
            app.Image27.ImageSource = 'SB_042.png';
            app.UsereadonlyversionLabel = uilabel(app.ODTestTab);
            app.UsereadonlyversionLabel.Text = 'Use read only version';
            app.NetscannerTab = uitab(app.TabGroup6);
            app.NetscannerTab.Title = 'Netscanner';
            app.Image44_2 = uiimage(app.NetscannerTab);
            app.Image44_2.ImageSource = 'SB_049.png';
            app.MemoryTab = uitab(app.TabGroup6);
            app.MemoryTab.Title = 'Memory';
            app.Image36_2 = uiimage(app.MemoryTab);
            app.Image36_2.ImageSource = 'SB_067.png';
            app.ReadPowerModuleandRemoteModuleMemoryLocationstoFileLabel = uilabel(app.MemoryTab);
            app.ReadPowerModuleandRemoteModuleMemoryLocationstoFileLabel.Text = 'Read Power Module and Remote Module Memory Locations to File';
            app.TrunkTab = uitab(app.MainTabGroup);
            app.TrunkTab.Title = 'Trunk';
            app.TabGroup9 = uitabgroup(app.TrunkTab);
            app.SitTab = uitab(app.TabGroup9);
            app.SitTab.Title = 'Sit';
            app.CoughTab = uitab(app.TabGroup9);
            app.CoughTab.Title = 'Cough';
            app.TransferTab = uitab(app.TabGroup9);
            app.TransferTab.Title = 'Transfer';
            app.TechnicianTab = uitab(app.MainTabGroup);
            app.TechnicianTab.Title = 'Technician';
            app.TabGroup7 = uitabgroup(app.TechnicianTab);
            app.MainTab = uitab(app.TabGroup7);
            app.MainTab.Title = 'Main';
            app.TechMain = uilabel(app.MainTab);
            app.TechMain.Text = 'Make this a separate app to separate from clnician tool';
            app.RadioTab_4 = uitab(app.TabGroup7);
            app.RadioTab_4.Title = 'Radio';
            app.Image32 = uiimage(app.RadioTab_4);
            app.Image32.ImageSource = 'SB_039.png';
            app.Image33 = uiimage(app.RadioTab_4);
            app.Image33.ImageSource = 'SB_040.png';
            app.Image34 = uiimage(app.RadioTab_4);
            app.Image34.ImageSource = 'SB_041.png';
            app.NetScannerTab = uitab(app.TabGroup7);
            app.NetScannerTab.Title = 'NetScanner';
            app.Image44_3 = uiimage(app.NetScannerTab);
            app.Image44_3.ImageSource = 'SB_049.png';
            app.ScriptEditTab = uitab(app.TabGroup7);
            app.ScriptEditTab.Title = 'Script Edit';
            app.Image42 = uiimage(app.ScriptEditTab);
            app.Image42.ImageSource = 'SB_050.png';
            app.SchedulerTab = uitab(app.TabGroup7);
            app.SchedulerTab.Title = 'Scheduler';
            app.Image43 = uiimage(app.SchedulerTab);
            app.Image43.ImageSource = 'SB_051.png';
            app.PDOTab = uitab(app.TabGroup7);
            app.PDOTab.Title = 'PDO';
            app.Image28 = uiimage(app.PDOTab);
            app.Image28.ImageSource = 'SB_044.png';
            app.Image29 = uiimage(app.PDOTab);
            app.Image29.ImageSource = 'SB_064.png';
            app.Image30 = uiimage(app.PDOTab);
            app.Image30.ImageSource = 'SB_065.png';
            app.Image31 = uiimage(app.PDOTab);
            app.Image31.ImageSource = 'SB_066.png';
            app.RemoteModTab = uitab(app.TabGroup7);
            app.RemoteModTab.Title = 'Remote Mod';
            app.Image38 = uiimage(app.RemoteModTab);
            app.Image38.ImageSource = 'SB_074.png';
            app.TabGroup8 = uitabgroup(app.RemoteModTab);
            app.EMGTab_2 = uitab(app.TabGroup8);
            app.EMGTab_2.Title = 'EMG';
            app.Image40 = uiimage(app.EMGTab_2);
            app.Image40.ImageSource = 'SB_076.png';
            app.StimTab = uitab(app.TabGroup8);
            app.StimTab.Title = 'Stim';
            app.Image39 = uiimage(app.StimTab);
            app.Image39.ImageSource = 'SB_077.png';
            app.Image41 = uiimage(app.RemoteModTab);
            app.Image41.ImageSource = 'SB_075.png';
            app.MemoryRestoreTab = uitab(app.TabGroup7);
            app.MemoryRestoreTab.Title = 'Memory Restore';
            app.Image36_3 = uiimage(app.MemoryRestoreTab);
            app.Image36_3.ImageSource = 'SB_067.png';
            app.Image37 = uiimage(app.MemoryRestoreTab);
            app.Image37.ImageSource = 'SB_068.png';
            app.HardwareTab_2 = uitab(app.TabGroup7);
            app.HardwareTab_2.Title = 'Hardware';
            app.TabGroup10 = uitabgroup(app.HardwareTab_2);
            app.ExternalTab = uitab(app.TabGroup10);
            app.ExternalTab.Title = 'External';
            app.TabGroup11 = uitabgroup(app.ExternalTab);
            app.RadioTab_5 = uitab(app.TabGroup11);
            app.RadioTab_5.Title = 'Radio';
            app.Image = uiimage(app.RadioTab_5);
            app.Image.ImageSource = 'SB_000.png';
            app.Image2 = uiimage(app.RadioTab_5);
            app.Image2.ImageSource = 'SB_001.png';
            app.TunerTab = uitab(app.TabGroup11);
            app.TunerTab.Title = 'Tuner';
            app.ChargingTab = uitab(app.TabGroup11);
            app.ChargingTab.Title = 'Charging';
            app.Image7 = uiimage(app.ChargingTab);
            app.Image7.ImageSource = 'SB_008.png';
            app.InternalTab = uitab(app.TabGroup10);
            app.InternalTab.Title = 'Internal';
            app.HardwareTabGroup = uitabgroup(app.InternalTab);
            app.ModulesTab = uitab(app.HardwareTabGroup);
            app.ModulesTab.Title = 'Modules';
            app.Image3 = uiimage(app.ModulesTab);
            app.Image3.ImageSource = 'SB_002.png';
            app.ChannelsTab = uitab(app.HardwareTabGroup);
            app.ChannelsTab.Title = 'Channels';
            app.Image4 = uiimage(app.ChannelsTab);
            app.Image4.ImageSource = 'SB_003.png';
            app.ConfigurationTab = uitab(app.HardwareTabGroup);
            app.ConfigurationTab.Title = 'Configuration';
            app.TabGroup = uitabgroup(app.ConfigurationTab);
            app.SyncTimingTab = uitab(app.TabGroup);
            app.SyncTimingTab.Title = 'Sync Timing';
            app.RemoteModuleConfigurationLabel = uilabel(app.SyncTimingTab);
            app.RemoteModuleConfigurationLabel.Text = 'Remote Module Configuration';
            app.Image5 = uiimage(app.SyncTimingTab);
            app.Image5.ImageSource = 'SB_004.png';
            app.Image6 = uiimage(app.SyncTimingTab);
            app.Image6.ImageSource = 'SB_005.png';
            app.OtherTab = uitab(app.TabGroup);
            app.OtherTab.Title = 'Other';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StoryBoard

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end