classdef accSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AccSettings                matlab.ui.Figure
        Menu                       matlab.ui.container.Menu
        OpenMenu                   matlab.ui.container.Menu
        SaveMenu                   matlab.ui.container.Menu
        SaveAsMenu                 matlab.ui.container.Menu
        LogMenu                    matlab.ui.container.Menu
        NewFileMenu                matlab.ui.container.Menu
        TabGroup                   matlab.ui.container.TabGroup
        PGTab                      matlab.ui.container.Tab
        TpdoActiveTable            matlab.ui.control.Table
        StatusButton               matlab.ui.control.Button
        AccAxes                    matlab.ui.control.UIAxes
        NodeDisplayDropDown        matlab.ui.control.DropDown
        ViewAccButton              matlab.ui.control.StateButton
        CommandAxes                matlab.ui.control.UIAxes
        SourceSwitchLabel          matlab.ui.control.Label
        SourceSwitch               matlab.ui.control.Switch
        XLabel                     matlab.ui.control.Label
        YLabel                     matlab.ui.control.Label
        ZLabel                     matlab.ui.control.Label
        AuxParTab                  matlab.ui.container.Tab
        BPTab                      matlab.ui.container.Tab
        SyncIntEditFieldLabel      matlab.ui.control.Label
        SyncIntEditField           matlab.ui.control.NumericEditField
        WriteBPButton              matlab.ui.control.Button
        ReadBPSettingsButton       matlab.ui.control.Button
        Panel                      matlab.ui.container.Panel
        MESNodeEditFieldLabel      matlab.ui.control.Label
        MESNodeEditField           matlab.ui.control.NumericEditField
        StimNodesEditFieldLabel    matlab.ui.control.Label
        StimNodesEditField         matlab.ui.control.EditField
        Messages                   matlab.ui.control.TextArea
        ClearMessageButton         matlab.ui.control.Button
        NetworkPanel               matlab.ui.container.Panel
        OnButton                   matlab.ui.control.Button
        OffButton                  matlab.ui.control.Button
        NetworkLamp                matlab.ui.control.Lamp
        StartPDOsButton            matlab.ui.control.Button
        PatientLamp                matlab.ui.control.Lamp
        WaitButton                 matlab.ui.control.Button
        FilenameEditFieldLabel     matlab.ui.control.Label
        FilenameEditField          matlab.ui.control.EditField
        Aux1Panel                  matlab.ui.container.Panel
        DelayEditFieldLabel        matlab.ui.control.Label
        DelayEditField             matlab.ui.control.NumericEditField
        MethodDropDownLabel        matlab.ui.control.Label
        MethodDropDown             matlab.ui.control.DropDown
        RampUpEditFieldLabel       matlab.ui.control.Label
        RampUpEditField            matlab.ui.control.NumericEditField
        RampDownEditFieldLabel     matlab.ui.control.Label
        RampDownEditField          matlab.ui.control.NumericEditField
        ChanDropDownLabel          matlab.ui.control.Label
        ChanDropDown               matlab.ui.control.DropDown
        OffsetEditFieldLabel       matlab.ui.control.Label
        OffsetEditField            matlab.ui.control.NumericEditField
        GainEditFieldLabel         matlab.ui.control.Label
        GainEditField              matlab.ui.control.NumericEditField
        FilterDepthEditFieldLabel  matlab.ui.control.Label
        FilterDepthEditField       matlab.ui.control.NumericEditField
        DirDropDownLabel           matlab.ui.control.Label
        DirDropDown                matlab.ui.control.DropDown
        NodeDropDownLabel          matlab.ui.control.Label
        AccNodeDropDown            matlab.ui.control.DropDown
        ReadButton                 matlab.ui.control.Button
        WriteButton                matlab.ui.control.Button
    end

    % I want to put all the data into variables first, then update the display, in order to make it easeier to load and
    % save the data to mat file later.
    
    properties (Access = public)
        rmParam=[];
        nnp=[];
        rmfilename;
        pathname;
        Aux1Par=[1,0,26,13,177,1,128,20,10]; % default for using Acc with node 4Y
        TpdoActive= logical([0,0,0,0,0,0]');% logical column array        
        TpdoTable=[];
        comIdx=1;  % command index
 %plotting
        plotbufx=[];
        plotbufy=[]
        plotbufz=[];
        plotbufc=[];
        px=[]; % plot x handle
        py=[]; % plot y handle
        pz=[]; % plot z handle
        pc=[]; % plot command handle
        y1=[]; % handle for Y line at offset
        y2=[]; % handle for y line at max
       lgd=[]; % legend handle
       
         % explore option of buffering all 28 data points in addition to logging them to facilitate switching between 
         % nodes or plotting multiple nodes.  
        plotallbuf=[]; % all recorded nodes
 % timer
        accTimer=[];
        plotcycle=4; %plot every fourth cycle making the update 5Hz
        cycle=0;
        ODval=double(zeros(1,28)); % array for read
        accsamp=50; %start with 5 seconds of data
        startSi=164; % where to start reading subindices of 1F53
        plotIndex=[1,5,9,13,17,21,25];  %starting subindex based on dropdown selection
        readlength=28; %subindices to read
        nodeMap=[9,1,2,3,4,5,6]; %how the subindices are arranged
        % if I make the dropdown match the nodeMap, then I can read the plotindex=(index*4)-3
        
        lastcounter=0;
        plotthis=0;
        oldtime=[];
        rabuffer=double([128,128,128,128,128,128,128, 128,128,128]);  %start with entire buffer so that we can update on the fly
        raptr=1;
    end
    
    methods (Access = private)
        
        
    end
    
    methods (Access = public)
        
        function getBPdata(app)
            app.nnp.enterWaiting();   % waiting mode
            success=app.nnp.networkOn();  % network on
            if success %true if successful
                app.NetworkLamp.Color=[0 1 0];
                app.TopMessage('Reading BP parameters...');
                pause(1); % wait for network to start
                                
                node=uint8(app.MESNodeEditField.Value);
                              
                rx=app.nnp.read(node,'2800',2,1,'uint8'); %Sync Interval
                if ~isempty(rx)
                    app.rmParam.bpSyncInt=double(rx);
                    app.SyncIntEditField.Value=app.rmParam.bpSyncInt;
                end
  
                app.TopMessage('Finished reading BP parameters');
            else
                app.TopMessage('Not able to start network');
            end
        end
                       
        function Write2BP(app)
            app.nnp.enterWaiting();   % waiting mode
            success=app.nnp.networkOn();  % network on
            if success %true if successful
                app.NetworkLamp.Color=[0 1 0];
                app.TopMessage('Writing BP parameters...');
                pause(1); % wait for network to start
                
                % update variables to reflect changes to the displayed tables
                % write data to the BP module
                % confirm what was written
                
                node=uint8(app.MESNodeEditField.Value);
                app.BPtables2Variables();
                % 2800 Sync Timing
                % 	1	Sync timing array [23]   app.rmParam.bpSyncSchedule
                % 	2	Sync interval
                % 3411 Ch 1 Configuration    app.rmParam.bpCh1
                % 	1	Gain
                % 	2	Depth (running average)
                % 	3	Threshold ZX
                % 	4	Threshold SSC
                % 	5	MAV Gain
                % 	6	MV Gain
                % 	7	WFL Gain
                % 3412 Ch 1 Features
                % 	6	MAV, WFL,Acc
                % 3511 Ch 2 Configuration    app.rmParam.bpCh1
                % 	1	Gain
                % 	2	Depth (running average)
                % 	3	Threshold ZX
                % 	4	Threshold SSC
                % 	5	MAV Gain
                % 	6	MV Gain
                % 	7	WFL Gain
                % 3512 Ch 2 Features
                % 	6	MAV, WFL,Acc
                % dataOut = write(NNP, node, indexOD, subIndexOD, writeData, writeType, numSubIndices)
%                 result = app.nnp.write(node, '2800', 1, uint8(app.rmParam.bpSyncSchedule), 'uint8');
%                 if ~isequal(result, 0)
%                     app.TopMessage('Error writing bp schedule');
%                 end
%                 
                result = app.nnp.write(node, '2800', 2, uint8(app.rmParam.bpSyncInt), 'uint8');
                if ~isequal(result, 0)
                    app.TopMessage('Error writing bp sync int')
                end
                
%                 for i=1:7
%                     result = app.nnp.write(node, '3411', i, uint8(app.rmParam.bpCh1(i)), 'uint8');  % block write not supported for RM
%                     
%                     if ~isequal(result, 0)
%                         app.TopMessage('Error writing bp cfg 1')
%                     end
%                 end
%                 result = app.nnp.write(node, '3412', 6, uint8(app.rmParam.bpCh1(8)), 'uint8');
%                 if ~isequal(result, 0)
%                     app.TopMessage('Error writing bp features 1')
%                 end
%                 for i=1:7
%                     result = app.nnp.write(node, '3511', 1, uint8(app.rmParam.bpCh2(1)), 'uint8');
%                     if ~isequal(result, 0)
%                         app.TopMessage('Error writing bp cfg 2')
%                     end
%                 end
%                 
%                 result = app.nnp.write(node, '3512', 6, uint8(app.rmParam.bpCh2(8)), 'uint8');
%                 if ~isequal(result, 0)
%                     app.TopMessage('Error writing bp features 2')
%                 end
                app.TopMessage('Finished writing BP Parameters...');
            end
        end
        
        
        function BPtables2Variables(app)
            app.rmParam.bpNode=app.MESNodeEditField.Value; %
        end
        
        function ClearMessages(app)
            app.Messages.Value=''; % clear messages
        end
        
        function TopMessage(app,message)
            app.Messages.Value=vertcat(message, string(app.Messages.Value)); % put new message on top
        end
        
        function quit = checkDoSave(app)
            % if the structure has changed, then need to check whether the user wants to save the current file before
            % opening another.
            quit = false;
            if ~isempty(app.rmfilename) %file already exists
                
                %check if any changes have been made
                savedValues = load(app.rmfilename);
                if ~isequal(savedValues.rmParam, app.rmParam)
                    
                    userResp = questdlg(sprintf(['Changes detected in RM parameter file.\n Do you want to save changes to:\n',...
                        '"%s"?\n\nHit Cancel and choose File/Save As if you want to save into a new file'], app.rmfilename),...
                        'RM Parameter File');
                    if isequal(userResp, 'Yes')
                        app.SaveMenuSelected();
                    elseif isequal(userResp, 'No')
                        %nothing to do here
                    else %Cancel
                        quit = true;
                    end
                end
            elseif ~isempty(app.rmParam) %no file exists yet, but something has been done
                userResp = questdlg('Save RM parameter File?','RM Parameter File');
                if isequal(userResp, 'Yes')
                    app.SaveAsMenuSelected();
                elseif isequal(userResp, 'No')
                    %nothing to do here
                else %Cancel
                    quit = true;
                end
            end
        end
        
        function writeErrCheck(app,result,message,node)
            if isempty(result)
                dmessage=strcat('failed to write ',message,' ',num2str(node));
                app.TopMessage(dmessage);
            end
            
        end
        
        function defaultTpdoTable(app)
            % determine length by PG nodes
            Node=str2num(app.StimNodesEditField.Value)'; %numerical list of nodes, transposed
            nPG=size(Node,1);
            Active=false(nPG,1); %logical array of 0's
            % create list of names based on PG nodes
            app.TpdoTable=table(Node,Active);
            app.TpdoActiveTable.Data=app.TpdoTable;
            % generate table
            % update table
            % made rows editable
        end
        
        
        function updateAcc(app,obj,event) % not sure whether obj and event are necessary for timer function since not used
            resp=app.nnp.read(7,'1f53',app.startSi,app.readlength,'uint8');
            if(length(resp)==app.readlength) % valid packet
                app.ODval=double(resp);
                % since acc samples at 40Hz, can skip the counter condition, but will log anyway
                
                % update buffers (start with just x,y,z but plan for more later
                pli=app.plotIndex(app.NodeDisplayDropDown.Value); % the value is mapped to the index in the read buffer
                new(1)=app.ODval(pli); % x
                new(2)=app.ODval(pli+1); % y
                new(3)=app.ODval(pli+2); % z

                % New Command is an N point running average.  I need to keep track of prior points
                 newCom=app.commandRA(new(app.comIdx)); % one of the points already being plotted
%                 % scale and gain

                 newCom=(newCom-app.OffsetEditField.Value)*app.GainEditField.Value;  % should change gain to gain*10 to allow fractions
                 newCom=min(255,newCom); % truncate at 255
                 newCom=max(newCom,0); % truncate at 0
                app.plotbufx(1:end-1)=app.plotbufx(2:end);
                app.plotbufx(end)=new(1);
                app.plotbufy(1:end-1)=app.plotbufy(2:end);
                app.plotbufy(end)=new(2);
                app.plotbufz(1:end-1)=app.plotbufz(2:end);
                app.plotbufz(end)=new(3);
                
                 app.plotbufc(1:end-1)=app.plotbufc(2:end);
                 app.plotbufc(end)=newCom;            
                % update the plots
                app.px.YData=app.plotbufx;
                app.py.YData=app.plotbufy;
                app.pz.YData=app.plotbufz;
                 app.pc.YData=app.plotbufc;
                % log the data
            end
        end
        
        function startAcc(app)
           app.oldtime=now();
            if app.nnp.getNetworkStatus()==1  % if network is on, then proceed, otherwise send message
                % check if in patient mode
%                app.saveRadio();
                % for max bandwidth, we need to eliminate radio retries, shorten timeouts, and turn of WOR
%                app.nnp.setRadio('timeout', 200, 'retries', 5');
%                app.nnp.worOff();
%                app.disableGraphInteractions(); % disable while running
                start(app.accTimer); 
            else
                app.TopMessage('Network is not ON');
                app.ViewAccButton.Value=false;              
            end
            % Here I could disable the interactions and reenable them when
            % finished recording. 
        end
        
        function stopAcc(app)
            stop(app.accTimer);
%            app.restoreRadio();
%            app.enableGraphInteractions();
        end
        
        function results = commandRA(app,newpt)
            % newpt is the most recent point
           depth=app.FilterDepthEditField.Value; %number of points to average
           ptr=app.raptr;
           % if depth has changed and is > depth, then reset
           ptr=ptr+1;
           if ptr>depth
               ptr=1;
           % replace current point
           end
           app.rabuffer(ptr)=newpt;
           results=sum(app.rabuffer(1:depth))/depth;
           app.raptr=ptr;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin<2
                NNP=[];
            else
                NNP=varargin{1};
            end
            if isempty(NNP)
                app.nnp = NNPAPI();
                if isempty(app.nnp.port)
                    msgbox('No port chosen for NNP Access Point')
                    delete(app.nnp)
                    return
                end
            else
                app.nnp = NNP;
            end
            % Note: In order to make the selected value a number rather than string, you have to asigne it at startup,
            % otherwise they will always be strings (a bug I think).   So here I am assigning it to be and index and
            % mapping that index to an array of pointers in the array for the index of the acc pdo values

            %read network status at 3004.1
            resp=app.nnp.read(7,'3004',1,'uint8');
            if length(resp)==1
                if resp==1
                    app.NetworkLamp.Color=[0,1,0];
                else
                    app.NetworkLamp.Color=[.9,.9,.9];
                end
            else
                app.NetworkLamp.Color=[.9,.9,.9];
                app.TopMessage('Could not read network status');
            end
            %read mode status at 2010.1 byte 2
            resp=app.nnp.read(7,'2010',1,'uint32');
            if length(resp)==1
                modebytes=typecast(resp,'uint8'); % get the bytes
                mode=modebytes(2); %just need byte 2 for mode
                if mode==5 %patient mode
                    app.PatientLamp.Color=[0,0,1];
                else
                    app.PatientLamp.Color=[.9,.9,.9];
                end
            else
                app.PatientLamp.Color=[.9,.9,.9];
                app.TopMessage('Could not read PM mode status');
            end           
            
            app.NodeDisplayDropDown.ItemsData=double([1:7])'; % to make this anything other than cell array, need to enter manually
            % changing the ItemsData resets the default to the first index, so I need to set the default again here
            app.NodeDisplayDropDown.Value=4; % there wasn't an easy way to read the default index, other than searching the 
            app.rmParam.bpSyncInt=1;
            app.rmParam.pgNodeList=str2num(app.StimNodesEditField.Value);
            app.rmParam.bpNode=app.MESNodeEditField.Value; % default node is 9            

            % default Tpdo data
           app.defaultTpdoTable();
           
           % acc timer function
          app.accTimer=timer();
          app.accTimer.Period=0.1;
          app.accTimer.ExecutionMode='fixedRate';
          app.accTimer.BusyMode='queue';
          app.accTimer.TimerFcn=@app.updateAcc;
          
          %default buffers
%            app.plotbufx=nan(app.accsamp,1); %default x buffer, 50 samples, 5 seconds
%            app.plotbufy=nan(app.accsamp,1); %default y buffer, 50 samples, 5 seconds
%            app.plotbufz=nan(app.accsamp,1); %default z buffer, 50 samples, 5 seconds
           
           app.plotbufx=zeros(app.accsamp,1)+138; %default x buffer, 50 samples, 5 seconds
           app.plotbufy=zeros(app.accsamp,1)+128; %default y buffer, 50 samples, 5 seconds
           app.plotbufz=zeros(app.accsamp,1)+118; %default z buffer, 50 samples, 5 seconds
           app.plotbufc=zeros(app.accsamp,1)+138; %default c buffer, 50 samples, 5 seconds
           
          % first set of plots
          % three plots on same graph

          app.px=plot(app.AccAxes,1:app.accsamp,app.plotbufx,"Color",[1,0,0]);
          hold(app.AccAxes);
          app.py=plot(app.AccAxes,1:app.accsamp,app.plotbufy,"Color",[0,1,0]);
%          hold(app.AccAxes);
          app.pz=plot(app.AccAxes,1:app.accsamp,app.plotbufz,"Color",[0,0,1]);
%          app.lgd=legend(app.AccAxes,'X','Y','Z','Location','northoutside');  % skip because too much space
          app.AccAxes.Title.String=app.NodeDisplayDropDown.Items(app.NodeDisplayDropDown.Value); % I think this will work?
          app.AccAxes.YLimMode='manual';
          app.AccAxes.YLim=[-1,256]; 
          % display gain and offset
          y1v=app.OffsetEditField.Value;
          app.y1=yline(app.AccAxes,y1v,'-.','Color',[0.5,0.5,0.5]);
          y2v=y1v+255/app.GainEditField.Value;
          app.y2=yline(app.AccAxes,y2v,'-.','Color',[0.5,0.5,0.5]);
          %display command
          newcolor=[0,0,0];          
          app.comIdx=str2num(app.ChanDropDown.Value)+1;
          newcolor(app.comIdx)=1;
          app.pc=plot(app.CommandAxes,1:app.accsamp,app.plotbufc,"Color",newcolor); % should match the index
          app.CommandAxes.YLimMode='manual';
          app.CommandAxes.YLim=[-5,260];
        end

        % Button pushed function: ReadBPSettingsButton
        function ReadBPSettingsButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.BPTab;
            app.getBPdata();
        end

        % Button pushed function: OffButton
        function OffButtonPushed(app, event)
            app.nnp.enterWaiting();   % waiting mode
            success=app.nnp.networkOff();  % network off
            if success %true if successful
                app.NetworkLamp.Color=[0.90,0.90,0.90];
                app.PatientLamp.Color=[0.90,0.90,0.90];
            else
                %need message
            end
        end

        % Button pushed function: WriteBPButton
        function WriteBPButtonPushed(app, event)
        %    app.Write2BP();
        end

        % Button pushed function: ClearMessageButton
        function ClearMessageButtonPushed(app, event)
            app.ClearMessages();
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            % open mat file to read in remote module data
            % start with a mat file that I create externally to see if I have all the necessary variables saved.

            if app.checkDoSave()
                return
            end
            
            [filename, pathname ] = uigetfile('*.mat', 'Open Remote Module parameters mat File');
            if filename == 0
                return
            else
                app.rmfilename = [pathname filename];
                app.FilenameEditField.Value=app.rmfilename;
            end
            
%             app.rmParam = load(app.rmfilename);
            in=load(app.rmfilename);
            if ~isfield(in, 'rmParam') % this file does not contain the right structure
                % here I could test also test the number of fields in rmParam
                msgbox('Remote Module  mat file must contain the structure variable named "rmParam". Please try again.');
            else

                app.rmParam=in.rmParam; %copy to app
                app.MESNodeEditField.Value=app.rmParam.bpNode;
                app.StimNodesEditField.Value=num2str(app.rmParam.pgNodeList);

            end
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
            % get a file name and save the data to that file
            % include a default file name.  How about rmParameters.mat
            [filename, pathname ] = uiputfile('*.mat', 'Save As Remote Module parameters mat File','rmParameters.mat');
            if filename == 0
                return
            else
                app.rmfilename = fullfile(pathname, filename);
                SaveMenuSelected(app, event);
                app.pathname=pathname;
                app.FilenameEditField.Value=app.rmfilename;
            end
            
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            %check for valid file name.   get new name if not valid
            % save data to file
            if isempty(app.rmfilename)
                SaveAsMenuSelected(app, event);
            else
                app.BPtables2Variables();

                rmParam=app.rmParam; % save as local variable to save
                save(app.rmfilename,'rmParam'); % save the whole structure as a variable, rather than individual
            end
            
        end

        % Close request function: AccSettings
        function AccSettingsCloseRequest(app, event)
            if app.checkDoSave()
                return
            end
% if I add rm help, here is where I would close those handles
            
            
            delete(app)
            
        end

        % Button pushed function: OnButton
        function OnButtonPushed(app, event)
            app.nnp.enterWaiting();   % waiting mode
            success=app.nnp.networkOn();  % network off
            if success %true if successful
                app.NetworkLamp.Color=[0.0,0.90,0.00];
            else
                %need message
            end   
        end

        % Cell edit callback: TpdoActiveTable
        function TpdoActiveTableCellEdit(app, event)
            indices = event.Indices; % row/column of Data
            newData = event.NewData;
            node=app.TpdoActiveTable.Data{indices(1),1};
            dmessage=sprintf('pdo for node %d',node);
            if newData(1) %select active
                send=1;
                dm='Enable ';
            else % select inactive
                send=253;
                dm='Disable ';
            end
            resp=app.nnp.write(node,'1800',2,send,'uint8');
            if isequal(resp, 0)
                app.TopMessage([dm,dmessage]);
                app.nnp.nmt(node, '0a');
            else
                app.TopMessage(['Failed -',dm,dmessage]);
            end
            % update the table

             app.TpdoTable.Active(node)=newData;
        end

        % Button pushed function: StatusButton
        function StatusButtonPushed(app, event)
            % read what is currently in the RMs
            % get the nodes
            % read status of network
            app.nnp.enterWaiting();
            success=app.nnp.networkOn();  % network on
            pause(1); % wait for network to start
            if success %true if successful
                app.NetworkLamp.Color=[0 1 0];
            end
            nodes= app.TpdoTable.Node;
            nPG=size(nodes,1);
            for i=1:nPG
                resp=app.nnp.read(nodes(i),'1800',2,'uint8');
                if length(resp)==1
                    if resp==1
                        app.TpdoTable.Active(i)=true;
                    else
                        app.TpdoTable.Active(i)=false;
                    end
                    app.TopMessage(num2str(nodes(i)));
                else
                    app.TopMessage(strcat('x-',num2str(nodes(i))));
                end               
            end
            % update the table
            app.TpdoActiveTable.Data=app.TpdoTable;
            %read the list of nodes
 %           if length(resp)==1
        end

        % Value changed function: ViewAccButton
        function ViewAccButtonValueChanged(app, event)
            value = app.ViewAccButton.Value;
            if value
                %read mode status at 2010.1 byte 2
                resp=app.nnp.read(7,'2010',1,'uint32');
                if length(resp)==1
                    modebytes=typecast(resp,'uint8'); % get the bytes
                    mode=modebytes(2); %just need byte 2 for mode
                    if mode==5 %patient mode
                        app.PatientLamp.Color=[0,0,1];
                        app.startAcc();
                        
                    else
                        app.PatientLamp.Color=[.9,.9,.9];
                        app.TopMessage('PM not in Patient mode');
                        app.ViewAccButton.Value = false; %reset the button
                    end
                else
                    app.PatientLamp.Color=[.9,.9,.9];
                    app.TopMessage('Could not read PM mode status');
                end
                
            else
                app.stopAcc();
            end
        end

        % Button pushed function: StartPDOsButton
        function StartPDOsButtonPushed(app, event)
% here I could check the status first to see if network on and patient mode
            app.nnp.enterWaiting();   % waiting mode
            success=app.nnp.networkOn();  % network off
            if success %true if successful
                app.NetworkLamp.Color=[0.0,0.90,0.00];
                app.TopMessage('Network Started');
            else
                app.TopMessage('Failed Network Started');
            end  
            pause(1); % wait for network to start before putting in patient mode
            success=app.nnp.enterPatient();
            if success %true if successful
                app.PatientLamp.Color=[0.0,0.00,0.90];
                app.TopMessage('Patient Mode Started');
            else
                app.TopMessage('Failed Patient Mode Started');
            end  
        end

        % Button pushed function: WaitButton
        function WaitButtonPushed(app, event)
        success=app.nnp.enterWaiting();   % waiting mode   
        if success %true if successful
            app.PatientLamp.Color=[0.90,0.90,0.90];
            app.TopMessage('Waiting Mode');
        else
            app.TopMessage('Failed Waiting Mode');
        end
        end

        % Value changed function: NodeDisplayDropDown
        function NodeDisplayDropDownValueChanged(app, event)
            value = app.NodeDisplayDropDown.Value;
            app.AccAxes.Title.String=app.NodeDisplayDropDown.Items(value);
        end

        % Button pushed function: WriteButton
        function WriteButtonPushed(app, event)
            %app.Aux1Par nine elements
            % 1 Aux1 Control Method
            % 2 Aux Delay Cycles
            % 3 Aux ramp up
            % 4 Aux ramp down
            % 5 Aux1 Acc Chan Subindex
            % 6 Aux1 Acc Orientation
            % 7 Aux Acc Offset
            % 8 Aux Acc Gain
            % 9 Aux1 Acc Filter Depth
            app.Aux1Par(1)=str2num(app.MethodDropDown.Value);
            app.Aux1Par(2)=app.DelayEditField.Value;
            app.Aux1Par(3)=app.RampUpEditField.Value;
            app.Aux1Par(4)=app.RampDownEditField.Value;
            app.Aux1Par(5)=str2num(app.AccNodeDropDown.Value)+str2num(app.ChanDropDown.Value);
            app.Aux1Par(6)=str2num(app.DirDropDown.Value);
            app.Aux1Par(7)=app.OffsetEditField.Value;
            app.Aux1Par(8)=app.GainEditField.Value;
            app.Aux1Par(9)=app.FilterDepthEditField.Value;
            % write to PM
            result = app.nnp.write(7, '1F57',109 , uint8(app.Aux1Par), 9,'uint8');            
            if ~isequal(result, 0)
                app.TopMessage('Error writing  to PM')
            else
                app.TopMessage('Aux1Par to PM')
                app.nnp.nmt(7, '0a');
            end

        end

        % Button pushed function: ReadButton
        function ReadButtonPushed(app, event)
            result = app.nnp.read(7, '1F57',109 , 9,'uint8');            
             if length(result)==9
             app.TopMessage('Aux1Par from PM')
             app.Aux1Par=double(result);               
            %app.Aux1Par nine elements
            % 1 Aux1 Control Method
            % 2 Aux Delay Cycles
            % 3 Aux ramp up
            % 4 Aux ramp down
            % 5 Aux1 Acc Chan Subindex
            % 6 Aux1 Acc Orientation
            % 7 Aux Acc Offset
            % 8 Aux Acc Gain
            % 9 Aux1 Acc Filter Depth
            app.MethodDropDown.Value=num2str(app.Aux1Par(1));
            app.DelayEditField.Value=app.Aux1Par(2);
            app.RampUpEditField.Value=app.Aux1Par(3);
            app.RampDownEditField.Value=app.Aux1Par(4);
            if app.Aux1Par(5)>=164
                base=app.Aux1Par(5)-164;
                ind=floor(base/4); %0 based with 9 1 2 3 4 5 6
%                nodearray=[9,1,2,3,4,5,6];
                chan=mod(base,ind) ; % value for dropdown in 0,1,2
                app.ChanDropDown.Value=num2str(chan);
                nodevalue=num2str(app.Aux1Par(5)-chan);
                app.AccNodeDropDown.Value=nodevalue;
                app.comIdx=str2num(app.ChanDropDown.Value)+1;  % this is not very efficient.  Maybe should not change while running

            else
                app.TopMessage('No Acc channel currently selected')
            end
            app.DirDropDown.Value=num2str(app.Aux1Par(6));
            app.OffsetEditField.Value= app.Aux1Par(7);
            app.GainEditField.Value=app.Aux1Par(8);
            app.FilterDepthEditField.Value=app.Aux1Par(9);
            else
                 app.TopMessage('Error writing  to PM')
            end  
           
 
        end

        % Value changed function: ChanDropDown
        function ChanDropDownValueChanged(app, event)
            value = app.ChanDropDown.Value;
            app.comIdx=str2num(value)+1;
            newcolor=[0,0,0];
            newcolor(app.comIdx)=1;
            app.pc.Color=newcolor; % should match the index
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AccSettings = uifigure('Visible', 'off');
            app.AccSettings.Name = 'Accelerometer Settings';
            app.AccSettings.CloseRequestFcn = createCallbackFcn(app, @AccSettingsCloseRequest, true);
            app.Menu = uimenu(app.AccSettings);
            app.Menu.Text = 'Menu';
            app.OpenMenu = uimenu(app.Menu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.Menu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.Menu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Text = 'SaveAs';
            app.LogMenu = uimenu(app.AccSettings);
            app.LogMenu.Text = 'Log';
            app.NewFileMenu = uimenu(app.LogMenu);
            app.NewFileMenu.Text = 'New File';
            app.TabGroup = uitabgroup(app.AccSettings);
            app.PGTab = uitab(app.TabGroup);
            app.PGTab.Title = 'PG';
            app.TpdoActiveTable = uitable(app.PGTab);
            app.TpdoActiveTable.ColumnName = {'Node'; 'Active'};
            app.TpdoActiveTable.RowName = {};
            app.TpdoActiveTable.ColumnEditable = [false true];
            app.TpdoActiveTable.CellEditCallback = createCallbackFcn(app, @TpdoActiveTableCellEdit, true);
            app.StatusButton = uibutton(app.PGTab, 'push');
            app.StatusButton.ButtonPushedFcn = createCallbackFcn(app, @StatusButtonPushed, true);
            app.StatusButton.Text = 'Status';
            app.AccAxes = uiaxes(app.PGTab);
            title(app.AccAxes, 'Acc')
            xlabel(app.AccAxes, 'Samples')
            ylabel(app.AccAxes, 'Acc')
            app.NodeDisplayDropDown = uidropdown(app.PGTab);
            app.NodeDisplayDropDown.Items = {'Node 9', 'Node 1', 'Node 2', 'Node 3', 'Node 4', 'Node 5', 'Node 6'};
            app.NodeDisplayDropDown.ItemsData = {'1', '5', '9', '13', '17', '21', '25', ''};
            app.NodeDisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @NodeDisplayDropDownValueChanged, true);
            app.NodeDisplayDropDown.Value = '13';
            app.ViewAccButton = uibutton(app.PGTab, 'state');
            app.ViewAccButton.ValueChangedFcn = createCallbackFcn(app, @ViewAccButtonValueChanged, true);
            app.ViewAccButton.Text = 'View Acc';
            app.CommandAxes = uiaxes(app.PGTab);
            title(app.CommandAxes, 'Command')
            xlabel(app.CommandAxes, 'Samples')
            ylabel(app.CommandAxes, 'Command')
            app.CommandAxes.YTick = [0 50 128 200 255];
            app.CommandAxes.YTickLabel = {'0'; '50'; '128'; '200'; '255'};
            app.SourceSwitchLabel = uilabel(app.PGTab);
            app.SourceSwitchLabel.Text = 'Source';
            app.SourceSwitch = uiswitch(app.PGTab, 'slider');
            app.SourceSwitch.Items = {'Calc', 'PM'};
            app.SourceSwitch.Value = 'Calc';
            app.XLabel = uilabel(app.PGTab);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.PGTab);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.PGTab);
            app.ZLabel.Text = 'Z';
            app.AuxParTab = uitab(app.TabGroup);
            app.AuxParTab.Title = 'AuxPar';
            app.BPTab = uitab(app.TabGroup);
            app.BPTab.Title = 'BP';
            app.SyncIntEditFieldLabel = uilabel(app.BPTab);
            app.SyncIntEditFieldLabel.Text = 'Sync Int';
            app.SyncIntEditField = uieditfield(app.BPTab, 'numeric');
            app.WriteBPButton = uibutton(app.BPTab, 'push');
            app.WriteBPButton.ButtonPushedFcn = createCallbackFcn(app, @WriteBPButtonPushed, true);
            app.WriteBPButton.Text = 'Write BP';
            app.ReadBPSettingsButton = uibutton(app.BPTab, 'push');
            app.ReadBPSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ReadBPSettingsButtonPushed, true);
            app.ReadBPSettingsButton.Text = {'Read BP'; 'Settings'};
            app.Panel = uipanel(app.AccSettings);
            app.Panel.Title = 'Panel';
            app.MESNodeEditFieldLabel = uilabel(app.Panel);
            app.MESNodeEditFieldLabel.Text = 'MES Node';
            app.MESNodeEditField = uieditfield(app.Panel, 'numeric');
            app.MESNodeEditField.Value = 9;
            app.StimNodesEditFieldLabel = uilabel(app.Panel);
            app.StimNodesEditFieldLabel.Text = 'Stim Nodes';
            app.StimNodesEditField = uieditfield(app.Panel, 'text');
            app.StimNodesEditField.Value = '1 2 3 4 5 6';
            app.Messages = uitextarea(app.Panel);
            app.ClearMessageButton = uibutton(app.Panel, 'push');
            app.ClearMessageButton.ButtonPushedFcn = createCallbackFcn(app, @ClearMessageButtonPushed, true);
            app.ClearMessageButton.Text = 'clear';
            app.NetworkPanel = uipanel(app.Panel);
            app.NetworkPanel.Title = 'Network';
            app.OnButton = uibutton(app.NetworkPanel, 'push');
            app.OnButton.ButtonPushedFcn = createCallbackFcn(app, @OnButtonPushed, true);
            app.OnButton.Text = 'On';
            app.OffButton = uibutton(app.NetworkPanel, 'push');
            app.OffButton.ButtonPushedFcn = createCallbackFcn(app, @OffButtonPushed, true);
            app.OffButton.Text = 'Off';
            app.NetworkLamp = uilamp(app.NetworkPanel);
            app.StartPDOsButton = uibutton(app.NetworkPanel, 'push');
            app.StartPDOsButton.ButtonPushedFcn = createCallbackFcn(app, @StartPDOsButtonPushed, true);
            app.StartPDOsButton.Text = {'Start'; 'PDOs'};
            app.PatientLamp = uilamp(app.NetworkPanel);
            app.WaitButton = uibutton(app.NetworkPanel, 'push');
            app.WaitButton.ButtonPushedFcn = createCallbackFcn(app, @WaitButtonPushed, true);
            app.WaitButton.Text = 'Wait';
            app.FilenameEditFieldLabel = uilabel(app.AccSettings);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.AccSettings, 'text');
            app.Aux1Panel = uipanel(app.AccSettings);
            app.Aux1Panel.Title = 'Aux1';
            app.DelayEditFieldLabel = uilabel(app.Aux1Panel);
            app.DelayEditFieldLabel.Text = 'Delay';
            app.DelayEditField = uieditfield(app.Aux1Panel, 'numeric');
            app.MethodDropDownLabel = uilabel(app.Aux1Panel);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.Aux1Panel);
            app.MethodDropDown.Items = {'Ramp', 'Acc'};
            app.MethodDropDown.ItemsData = {'0', '1'};
            app.MethodDropDown.Value = '0';
            app.RampUpEditFieldLabel = uilabel(app.Aux1Panel);
            app.RampUpEditFieldLabel.Text = 'Ramp Up';
            app.RampUpEditField = uieditfield(app.Aux1Panel, 'numeric');
            app.RampUpEditField.Value = 26;
            app.RampDownEditFieldLabel = uilabel(app.Aux1Panel);
            app.RampDownEditFieldLabel.Text = 'Ramp Down';
            app.RampDownEditField = uieditfield(app.Aux1Panel, 'numeric');
            app.RampDownEditField.Value = 13;
            app.ChanDropDownLabel = uilabel(app.Aux1Panel);
            app.ChanDropDownLabel.Text = 'Chan';
            app.ChanDropDown = uidropdown(app.Aux1Panel);
            app.ChanDropDown.Items = {'X', 'Y', 'Z'};
            app.ChanDropDown.ItemsData = {'0', '1', '2'};
            app.ChanDropDown.ValueChangedFcn = createCallbackFcn(app, @ChanDropDownValueChanged, true);
            app.ChanDropDown.Value = '1';
            app.OffsetEditFieldLabel = uilabel(app.Aux1Panel);
            app.OffsetEditFieldLabel.Text = 'Offset';
            app.OffsetEditField = uieditfield(app.Aux1Panel, 'numeric');
            app.OffsetEditField.Value = 128;
            app.GainEditFieldLabel = uilabel(app.Aux1Panel);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.Aux1Panel, 'numeric');
            app.GainEditField.Value = 20;
            app.FilterDepthEditFieldLabel = uilabel(app.Aux1Panel);
            app.FilterDepthEditFieldLabel.Text = 'Filter Depth';
            app.FilterDepthEditField = uieditfield(app.Aux1Panel, 'numeric');
            app.FilterDepthEditField.Value = 5;
            app.DirDropDownLabel = uilabel(app.Aux1Panel);
            app.DirDropDownLabel.Text = 'Dir';
            app.DirDropDown = uidropdown(app.Aux1Panel);
            app.DirDropDown.Items = {'Positive', 'Negative'};
            app.DirDropDown.ItemsData = {'0', '1'};
            app.DirDropDown.Value = '0';
            app.NodeDropDownLabel = uilabel(app.Aux1Panel);
            app.NodeDropDownLabel.Text = 'Node';
            app.AccNodeDropDown = uidropdown(app.Aux1Panel);
            app.AccNodeDropDown.Items = {'1', '2', '3', '4', '5', '6', '9'};
            app.AccNodeDropDown.ItemsData = {'168', '172', '176', '180', '184', '188', '164'};
            app.AccNodeDropDown.Value = '176';
            app.ReadButton = uibutton(app.Aux1Panel, 'push');
            app.ReadButton.ButtonPushedFcn = createCallbackFcn(app, @ReadButtonPushed, true);
            app.ReadButton.Text = 'Read';
            app.WriteButton = uibutton(app.Aux1Panel, 'push');
            app.WriteButton.ButtonPushedFcn = createCallbackFcn(app, @WriteButtonPushed, true);
            app.WriteButton.Text = 'Write';
            % Show the figure after all components are created
            app.AccSettings.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = accSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AccSettings)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AccSettings)
        end
    end
end