classdef backupRestoreMemory < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BackupRestoreMemory          matlab.ui.Figure
        RMBackupNameEditField        matlab.ui.control.EditField
        PMBackupNameEditField        matlab.ui.control.EditField
        BackupandRestoreMemoryLabel  matlab.ui.control.Label
        FolderButton                 matlab.ui.control.Button
        FolderEditField              matlab.ui.control.EditField
        AssumethatyouhavealreadyrunnnpNNPAPIandpassednnptothisappLabel  matlab.ui.control.Label
        MessagesTextAreaLabel        matlab.ui.control.Label
        MessagesTextArea             matlab.ui.control.TextArea
        Label                        matlab.ui.control.Label
        UserEditFieldLabel           matlab.ui.control.Label
        UserEditField                matlab.ui.control.NumericEditField
        PMBackupNameLabel            matlab.ui.control.Label
        RMBackupNameLabel            matlab.ui.control.Label
        PreparationPanel             matlab.ui.container.Panel
        WOROffButton                 matlab.ui.control.Button
        DisableScriptsButton         matlab.ui.control.Button
        RadioConfigButton            matlab.ui.control.Button
        TestConnectionButton         matlab.ui.control.Button
        RemoteModulesLabel           matlab.ui.control.Label
        RemoteModulesEditField       matlab.ui.control.EditField
        NetworkOnButton              matlab.ui.control.Button
        BackupMemoryPanel            matlab.ui.container.Panel
        BackupPMButton               matlab.ui.control.Button
        BackupRMButton               matlab.ui.control.Button
        BackupScriptsButton          matlab.ui.control.Button
        TurnonCoilfromCECTBEFOREbackupscriptsLabel  matlab.ui.control.Label
        RestoreMemoryPanel           matlab.ui.container.Panel
        RestorePMButton              matlab.ui.control.Button
        RestoreRMButton              matlab.ui.control.Button
        RestoreScriptsButton         matlab.ui.control.Button
        SetDateTimeButton            matlab.ui.control.Button
        TurnonCoilfromCECTBEFORErestorescriptsLabel  matlab.ui.control.Label
        RestartScriptsPanel          matlab.ui.container.Panel
        StartScriptsButton           matlab.ui.control.Button
        WOROnButton                  matlab.ui.control.Button
        NetworkOffButton             matlab.ui.control.Button
        OtherHelpfulDataPanel        matlab.ui.container.Panel
        RemoteModuleCurrentConfigurationButton  matlab.ui.control.Button
        ScriptBackupNameEditField    matlab.ui.control.EditField
        ScriptBackupNameLabel        matlab.ui.control.Label
        SelectBackupButton           matlab.ui.control.StateButton
        SelectRestoreButton          matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        nnp=[];
        selpath=pwd;% get the current working directory
        
        RFileID=[]; % File ID for Retore File
        LogFileName='TempLogFileName.bin'; % Save this to a temporary file
        LFileID=[]; % File ID for Log File
        PMBackupName='PowerModuleBackuptemp.mat'; % file name for Power Module
        RMBackupName='RemoteModuleMBackuptemp.mat'; % file name for Remote Modules
        ScriptBackupName='ScriptBackuptemp.mat'; % file name for Scripts
        PMfileID
       callingFigure=[]; %handle to calling figure
    end
    
    methods (Access = public)
        
        function TopMessage(app,message)
                app.MessagesTextArea.Value=vertcat(message, string(app.MessagesTextArea.Value)); % put new message on top
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin<2
                NNP=[];
            else
                NNP=varargin{1};
            end
            if isempty(NNP)
                app.nnp = NNPAPI();
                if isempty(app.nnp.port)
                    msgbox('No port chosen for NNP Access Point')
                    delete(app.nnp)
                    return
                end
            else
                app.nnp = NNP;
            end
            if nargin>2
                app.callingFigure=varargin{2};
            end

            
        end

        % Button pushed function: FolderButton
        function FolderButtonPushed(app, event)
            %             app.selpath = uigetdir(app.selpath,'Directory to store Log files');
            %             figure(app.BackupRestoreMemory); % Restore focus to getPMLog window
            %             app.FolderEditField.Value=app.selpath;
            %
            app.selpath = uigetdir(app.selpath,'Folder to store Backup files');
            figure(app.BackupRestoreMemory); % Restore focus to this window
            app.FolderEditField.Value=app.selpath;
            app.FolderButton.BackgroundColor=[0.96,0.96,0.96]; % from [1,.9,.6]
            %
            userstring=sprintf('P%d_',int16(app.UserEditField.Value));
            timestring=sprintf('_%02.0f',datevec(now));
            app.PMBackupNameEditField.Value=[userstring,'PowerModuleBackup',timestring,'.mat'];
            %
            app.PMBackupName=fullfile(app.FolderEditField.Value,app.PMBackupNameEditField.Value);
            %
            userstring=sprintf('P%d_',int16(app.UserEditField.Value));
            timestring=sprintf('_%02.0f',datevec(now));
            app.RMBackupNameEditField.Value=[userstring,'RemoteModuleBackup',timestring,'.mat'];
            %
            app.RMBackupName=fullfile(app.FolderEditField.Value,app.RMBackupNameEditField.Value);
            %
            userstring=sprintf('P%d_',int16(app.UserEditField.Value));
            timestring=sprintf('_%02.0f',datevec(now));
            app.ScriptBackupNameEditField.Value=[userstring,'ScriptBackup',timestring,'.mat'];
            %
            app.ScriptBackupName=fullfile(app.FolderEditField.Value,app.ScriptBackupNameEditField.Value);
            
        end

        % Button pushed function: RadioConfigButton
        function RadioConfigButtonPushed(app, event)
            radioconfig(app.nnp);
        end

        % Button pushed function: TestConnectionButton
        function TestConnectionButtonPushed(app, event)
            modePM=app.nnp.read(7,'2010',1, 'uint8'); % this should produce an array that I need to convert to string
            dmessage=sprintf('%d ',modePM);
           app.TopMessage(dmessage);% for now there is no way to go to bottom programatically, so don't append
        end

        % Button pushed function: WOROffButton
        function WOROffButtonPushed(app, event)
            if app.nnp.worOff
                dmessage='WOR Off';
            else
                dmessage='Error setting WOR Off';
            end
            app.TopMessage(dmessage);
        end

        % Button pushed function: WOROnButton
        function WOROnButtonPushed(app, event)
            if app.nnp.worOn(20)
                dmessage='WOR On';
            else
                dmessage='Error setting WOR On';
            end
            app.TopMessage(dmessage);
        end

        % Button pushed function: DisableScriptsButton
        function DisableScriptsButtonPushed(app, event)
            app.nnp.enterWaiting();
            %app.nnp.networkOff();
            app.nnp.nmt(7, '8F'); %disable scripts
            dmessage='Scripts Disabled';
            app.TopMessage(dmessage);
        end

        % Button pushed function: StartScriptsButton
        function StartScriptsButtonPushed(app, event)
            app.nnp.nmt(7, '8E'); %enable scripts
            app.nnp.nmt(7,'B3',7); % Run startup script
            dmessage='Scripts Enabled and Startup Script Run';
            app.TopMessage(dmessage);
        end

        % Button pushed function: RestoreScriptsButton
        function RestoreScriptsButtonPushed(app, event)
        defaultname=fullfile(app.selpath,'*.mat');
        [fn, pn]=uigetfile(defaultname,'Scripts Backup File to Restore');
        RestoreFileName=fullfile(pn, fn);
        d = load(RestoreFileName);
        try
            backupRestoreScripts(app.nnp, d.scriptData);
             dmessage='Scripts are Restored';
            app.TopMessage(dmessage);
            app.nnp.setRadio('worInt', 20);

        catch
%             disp('could not open restoreData from file')
            dmessage='could not open restoreData from file';
            app.TopMessage(dmessage);
        end
        
        end

        % Button pushed function: BackupPMButton
        function BackupPMButtonPushed(app, event)
         %Backup PM to file
          restoreData = backupRestorePM(app.nnp);
          save(app.PMBackupName, 'restoreData');
          if size(restoreData,2)==401 % earlier than PM version 405
              % create a version that can be used with 405 and above
              % Start with PM399 restoreData which is 401 element cell array
              a=restoreData(1:157);
              b=restoreData(170:223);
              c=restoreData(242:370);
              c{29}=150;
              d=num2cell(zeros(1,50)); %makes array of zeros
              e=restoreData(371:401);
              % Concatenate them together to get the new restoreData
              restoreData405=[a,b,c,d,e];
              restoreData=restoreData405;
              [fp,fname,fext] = fileparts(app.PMBackupName); 
              PMBackupName405=fullfile(fp, strcat(fname,'405',fext));  %appended backup name 
              save(PMBackupName405, 'restoreData');% save as mat file
          end
         
         dmessage='Finished backing up PM';
        app.TopMessage(dmessage);
        end

        % Button pushed function: BackupRMButton
        function BackupRMButtonPushed(app, event)
          
          remotemodules=uint8(eval(app.RemoteModulesEditField.Value)); % saved as text in edit field
          restoreData = backupRestoreRM(app.nnp, remotemodules);
          save(app.RMBackupName, 'restoreData');
          dmessage='Finished backing up Remote Modules';
        app.TopMessage(dmessage);
        end

        % Button pushed function: RestorePMButton
        function RestorePMButtonPushed(app, event)
         defaultname=fullfile(app.selpath,'*.mat');
        [fn, pn]=uigetfile(defaultname,'Power Module Backup File to Restore');
        RestoreFileName=fullfile(pn, fn);
        d = load(RestoreFileName);
        try
            backupRestorePM(app.nnp, d.restoreData);
            dmessage='Power Module Memory Restored';
            app.TopMessage(dmessage);
        catch
            %             disp('could not open restoreData from file')
            dmessage='could not open restoreData from file';
            app.TopMessage(dmessage);
        end
        end

        % Button pushed function: RestoreRMButton
        function RestoreRMButtonPushed(app, event)
        defaultname=fullfile(app.selpath,'*.mat');
        [fn, pn]=uigetfile(defaultname,'Remote Module Backup File to Restore');
        RestoreFileName=fullfile(pn, fn);
        d = load(RestoreFileName);
        try
            remotemodules=uint8(eval(app.RemoteModulesEditField.Value)); % saved as text in edit field
            backupRestoreRM(app.nnp, remotemodules, d.restoreData);
             dmessage='Remote Modules Memory Restored';
            app.TopMessage(dmessage);
        catch
%             disp('could not open restoreData from file')
            dmessage='could not open restoreData from file';
            app.TopMessage(dmessage);
        end
        end

        % Button pushed function: BackupScriptsButton
        function BackupScriptsButtonPushed(app, event)
            scriptData = backupRestoreScripts(app.nnp);
            save(app.ScriptBackupName, 'scriptData');
          dmessage='Finished backing up Scripts';
        app.TopMessage(dmessage);
        end

        % Button pushed function: 
        % RemoteModuleCurrentConfigurationButton
        function RemoteModuleCurrentConfigurationButtonPushed(app, event)
            rmParametersStatus(app.nnp);
        end

        % Button pushed function: SetDateTimeButton
        function SetDateTimeButtonPushed(app, event)
        if pmDateTimeSet(app.nnp)
            dmessage='Error setting data and time';
        else
            dmessage='Date and Time updated in PM';
        end
        app.TopMessage(dmessage);
        end

        % Button pushed function: NetworkOnButton
        function NetworkOnButtonPushed(app, event)
            if app.nnp.enterWaiting()
                if app.nnp.networkOn()
                    dmessage='Network On';
                else
                    dmessage='Network On failed';
                end
            else
                dmessage='Waiting command failed';
            end
           app.TopMessage(dmessage);
        end

        % Button pushed function: NetworkOffButton
        function NetworkOffButtonPushed(app, event)
            if app.nnp.enterWaiting()
                if app.nnp.networkOff()
                    dmessage='Network Off';
                else
                    dmessage='Network Off failed';
                end
            else
                dmessage='Waiting command failed';
            end
           app.TopMessage(dmessage);
        end

        % Callback function
        function WOROnButton_2Pushed(app, event)
           if app.nnp.worOn(20)
            dmessage='WOR On';
           else 
               dmessage='Error setting WOR On';
           end
           app.TopMessage(dmessage);
        end

        % Value changed function: SelectBackupButton
        function SelectBackupButtonValueChanged(app, event)
            value = app.SelectBackupButton.Value;
            % Toggle row of buttons to enable
            if value % when true show backup panel
                app.RestoreMemoryPanel.Visible=false;
                app.BackupMemoryPanel.Visible=true;
                app.SelectRestoreButton.Value=false;
            else % when false high back and show restore
                app.RestoreMemoryPanel.Visible=true;
                app.BackupMemoryPanel.Visible=false;
                app.SelectRestoreButton.Value=true;              
            end

        end

        % Value changed function: SelectRestoreButton
        function SelectRestoreButtonValueChanged(app, event)
            value = app.SelectRestoreButton.Value;
             if value % when true show backup panel
                app.RestoreMemoryPanel.Visible=true;
                app.BackupMemoryPanel.Visible=false;
                app.SelectBackupButton.Value=false;
            else % when false high back and show restore
                app.RestoreMemoryPanel.Visible=false;
                app.BackupMemoryPanel.Visible=true;                
                app.SelectBackupButton.Value=true;
            end
        end

        % Close request function: BackupRestoreMemory
        function BackupRestoreMemoryCloseRequest(app, event)
            if ishandle(app.callingFigure) % check that is is valid
                figure(app.callingFigure); % bring to front
            end
            
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BackupRestoreMemory = uifigure('Visible', 'off');
            app.BackupRestoreMemory.Name = 'BackupRestoreMemory';
            app.BackupRestoreMemory.CloseRequestFcn = createCallbackFcn(app, @BackupRestoreMemoryCloseRequest, true);
            app.RMBackupNameEditField = uieditfield(app.BackupRestoreMemory, 'text');
            app.RMBackupNameEditField.Editable = 'off';
            app.PMBackupNameEditField = uieditfield(app.BackupRestoreMemory, 'text');
            app.PMBackupNameEditField.Editable = 'off';
            app.BackupandRestoreMemoryLabel = uilabel(app.BackupRestoreMemory);
            app.BackupandRestoreMemoryLabel.Text = 'Backup and Restore PM and RM Memory and Configurations ';
            app.FolderButton = uibutton(app.BackupRestoreMemory, 'push');
            app.FolderButton.ButtonPushedFcn = createCallbackFcn(app, @FolderButtonPushed, true);
            app.FolderButton.Text = 'Folder';
            app.FolderEditField = uieditfield(app.BackupRestoreMemory, 'text');
            app.FolderEditField.Editable = 'off';
            app.AssumethatyouhavealreadyrunnnpNNPAPIandpassednnptothisappLabel = uilabel(app.BackupRestoreMemory);
            app.AssumethatyouhavealreadyrunnnpNNPAPIandpassednnptothisappLabel.Text = 'Assume that you have already run nnp=NNPAPI and passed nnp to this app';
            app.MessagesTextAreaLabel = uilabel(app.BackupRestoreMemory);
            app.MessagesTextAreaLabel.Text = 'Messages';
            app.MessagesTextArea = uitextarea(app.BackupRestoreMemory);
            app.Label = uilabel(app.BackupRestoreMemory);
            app.Label.Text = {'need to add clear button and append messages onto the string each time a new message is'; 'added to the queue. when setting scroll location is available.'};
            app.UserEditFieldLabel = uilabel(app.BackupRestoreMemory);
            app.UserEditFieldLabel.Text = 'User';
            app.UserEditField = uieditfield(app.BackupRestoreMemory, 'numeric');
            app.UserEditField.ValueDisplayFormat = '%d';
            app.UserEditField.Value = 125;
            app.PMBackupNameLabel = uilabel(app.BackupRestoreMemory);
            app.PMBackupNameLabel.Text = 'PM Backup Name';
            app.RMBackupNameLabel = uilabel(app.BackupRestoreMemory);
            app.RMBackupNameLabel.Text = 'RM Backup Name';
            app.PreparationPanel = uipanel(app.BackupRestoreMemory);
            app.PreparationPanel.Title = 'Preparation';
            app.WOROffButton = uibutton(app.PreparationPanel, 'push');
            app.WOROffButton.ButtonPushedFcn = createCallbackFcn(app, @WOROffButtonPushed, true);
            app.WOROffButton.Text = {'WOR'; 'Off'};
            app.DisableScriptsButton = uibutton(app.PreparationPanel, 'push');
            app.DisableScriptsButton.ButtonPushedFcn = createCallbackFcn(app, @DisableScriptsButtonPushed, true);
            app.DisableScriptsButton.Text = {'Disable'; 'Scripts'};
            app.RadioConfigButton = uibutton(app.PreparationPanel, 'push');
            app.RadioConfigButton.ButtonPushedFcn = createCallbackFcn(app, @RadioConfigButtonPushed, true);
            app.RadioConfigButton.Text = {'Radio'; 'Config'};
            app.TestConnectionButton = uibutton(app.PreparationPanel, 'push');
            app.TestConnectionButton.ButtonPushedFcn = createCallbackFcn(app, @TestConnectionButtonPushed, true);
            app.TestConnectionButton.Text = {'Test'; 'Connection'};
            app.RemoteModulesLabel = uilabel(app.PreparationPanel);
            app.RemoteModulesLabel.Text = 'Remote Modules';
            app.RemoteModulesEditField = uieditfield(app.PreparationPanel, 'text');
            app.RemoteModulesEditField.Value = '[1:6,9]';
            app.NetworkOnButton = uibutton(app.PreparationPanel, 'push');
            app.NetworkOnButton.ButtonPushedFcn = createCallbackFcn(app, @NetworkOnButtonPushed, true);
            app.NetworkOnButton.Text = {'Network'; 'On'};
            app.BackupMemoryPanel = uipanel(app.BackupRestoreMemory);
            app.BackupMemoryPanel.Title = 'Backup Memory';
            app.BackupPMButton = uibutton(app.BackupMemoryPanel, 'push');
            app.BackupPMButton.ButtonPushedFcn = createCallbackFcn(app, @BackupPMButtonPushed, true);
            app.BackupPMButton.Tooltip = {'Create a .mat file of restoreData.  If earlier than PM405'; ' a second copy is created for 405 and above'};
            app.BackupPMButton.Text = {'Backup'; 'PM'};
            app.BackupRMButton = uibutton(app.BackupMemoryPanel, 'push');
            app.BackupRMButton.ButtonPushedFcn = createCallbackFcn(app, @BackupRMButtonPushed, true);
            app.BackupRMButton.Tooltip = {''};
            app.BackupRMButton.Text = {'Backup'; 'RM'};
            app.BackupScriptsButton = uibutton(app.BackupMemoryPanel, 'push');
            app.BackupScriptsButton.ButtonPushedFcn = createCallbackFcn(app, @BackupScriptsButtonPushed, true);
            app.BackupScriptsButton.Text = {'Backup'; 'Scripts'};
            app.TurnonCoilfromCECTBEFOREbackupscriptsLabel = uilabel(app.BackupMemoryPanel);
            app.TurnonCoilfromCECTBEFOREbackupscriptsLabel.Text = {'Turn on Coil'; 'from CE/CT'; 'BEFORE '; 'backup scripts'};
            app.RestoreMemoryPanel = uipanel(app.BackupRestoreMemory);
            app.RestoreMemoryPanel.Title = 'Restore Memory';
            app.RestoreMemoryPanel.Visible = 'off';
            app.RestorePMButton = uibutton(app.RestoreMemoryPanel, 'push');
            app.RestorePMButton.ButtonPushedFcn = createCallbackFcn(app, @RestorePMButtonPushed, true);
            app.RestorePMButton.Tooltip = {'GUI will open to select .mat file with restoreData'};
            app.RestorePMButton.Text = {'Restore'; 'PM'};
            app.RestoreRMButton = uibutton(app.RestoreMemoryPanel, 'push');
            app.RestoreRMButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreRMButtonPushed, true);
            app.RestoreRMButton.Text = {'Restore'; 'RM'};
            app.RestoreScriptsButton = uibutton(app.RestoreMemoryPanel, 'push');
            app.RestoreScriptsButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreScriptsButtonPushed, true);
            app.RestoreScriptsButton.Text = {'Restore'; 'Scripts'};
            app.SetDateTimeButton = uibutton(app.RestoreMemoryPanel, 'push');
            app.SetDateTimeButton.ButtonPushedFcn = createCallbackFcn(app, @SetDateTimeButtonPushed, true);
            app.SetDateTimeButton.Text = {'Set Date'; '& Time'};
            app.TurnonCoilfromCECTBEFORErestorescriptsLabel = uilabel(app.RestoreMemoryPanel);
            app.TurnonCoilfromCECTBEFORErestorescriptsLabel.Text = {'Turn on Coil'; 'from CE/CT'; 'BEFORE '; 'restore scripts'};
            app.RestartScriptsPanel = uipanel(app.BackupRestoreMemory);
            app.RestartScriptsPanel.Title = 'Restart Scripts';
            app.StartScriptsButton = uibutton(app.RestartScriptsPanel, 'push');
            app.StartScriptsButton.ButtonPushedFcn = createCallbackFcn(app, @StartScriptsButtonPushed, true);
            app.StartScriptsButton.Text = {'Start'; 'Scripts'};
            app.WOROnButton = uibutton(app.RestartScriptsPanel, 'push');
            app.WOROnButton.ButtonPushedFcn = createCallbackFcn(app, @WOROnButtonPushed, true);
            app.WOROnButton.Text = {'WOR'; 'On'};
            app.NetworkOffButton = uibutton(app.RestartScriptsPanel, 'push');
            app.NetworkOffButton.ButtonPushedFcn = createCallbackFcn(app, @NetworkOffButtonPushed, true);
            app.NetworkOffButton.Text = {'Network'; 'Off'};
            app.OtherHelpfulDataPanel = uipanel(app.BackupRestoreMemory);
            app.OtherHelpfulDataPanel.Title = 'Other Helpful Data';
            app.RemoteModuleCurrentConfigurationButton = uibutton(app.OtherHelpfulDataPanel, 'push');
            app.RemoteModuleCurrentConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @RemoteModuleCurrentConfigurationButtonPushed, true);
            app.RemoteModuleCurrentConfigurationButton.Text = {'Remote Module'; 'Current Configuration'};
            app.ScriptBackupNameEditField = uieditfield(app.BackupRestoreMemory, 'text');
            app.ScriptBackupNameEditField.Editable = 'off';
            app.ScriptBackupNameLabel = uilabel(app.BackupRestoreMemory);
            app.ScriptBackupNameLabel.Text = 'Script Backup Name';
            app.SelectBackupButton = uibutton(app.BackupRestoreMemory, 'state');
            app.SelectBackupButton.ValueChangedFcn = createCallbackFcn(app, @SelectBackupButtonValueChanged, true);
            app.SelectBackupButton.Text = {'Select'; 'Backup'};
            app.SelectBackupButton.Value = true;
            app.SelectRestoreButton = uibutton(app.BackupRestoreMemory, 'state');
            app.SelectRestoreButton.ValueChangedFcn = createCallbackFcn(app, @SelectRestoreButtonValueChanged, true);
            app.SelectRestoreButton.Text = {'Select'; 'Restore'};
            % Show the figure after all components are created
            app.BackupRestoreMemory.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = backupRestoreMemory(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BackupRestoreMemory)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BackupRestoreMemory)
        end
    end
end