classdef bptestf < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BP2CProductionTestUIFigure   matlab.ui.Figure
        UserEditField                matlab.ui.control.NumericEditField
        UserEditFieldLabel           matlab.ui.control.Label
        NewLogFile                   matlab.ui.control.Button
        FolderEditField              matlab.ui.control.EditField
        FolderButton                 matlab.ui.control.Button
        LogFilenameEditField         matlab.ui.control.EditField
        LogEMGSwitch                 matlab.ui.control.Switch
        LogEMGLabel                  matlab.ui.control.Label
        PanelAccel                   matlab.ui.container.Panel
        CntEditField                 matlab.ui.control.NumericEditField
        EditFieldLabel               matlab.ui.control.Label
        ZEditField                   matlab.ui.control.NumericEditField
        ZLabel                       matlab.ui.control.Label
        YEditField                   matlab.ui.control.NumericEditField
        YLabel                       matlab.ui.control.Label
        XEditField                   matlab.ui.control.NumericEditField
        XLabel                       matlab.ui.control.Label
        PMVNETEditField              matlab.ui.control.NumericEditField
        PMVNETVEditFieldLabel        matlab.ui.control.Label
        VICEditField                 matlab.ui.control.NumericEditField
        VICADUEditFieldLabel         matlab.ui.control.Label
        VINEditField                 matlab.ui.control.NumericEditField
        VINADUEditFieldLabel         matlab.ui.control.Label
        TemperatureEditField         matlab.ui.control.NumericEditField
        TemperatureCEditFieldLabel   matlab.ui.control.Label
        VNETVINCheckButton           matlab.ui.control.StateButton
        StartButton                  matlab.ui.control.StateButton
        AccelAxes                    matlab.ui.control.UIAxes
        PanelEMG                     matlab.ui.container.Panel
        BaselineEditField            matlab.ui.control.NumericEditField
        BaselineADULabel             matlab.ui.control.Label
        CommonModeInputsignalexpectedtobe1000xDifferentialLabel  matlab.ui.control.Label
        FrequencyEditField           matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel    matlab.ui.control.Label
        ViewEMGButton                matlab.ui.control.StateButton
        ShowFFTCheckBox              matlab.ui.control.CheckBox
        CommonAmpEditField           matlab.ui.control.NumericEditField
        DifferentialAmpEditField     matlab.ui.control.NumericEditField
        VPPRTImVLabel                matlab.ui.control.Label
        GainDropDown                 matlab.ui.control.DropDown
        ChannelDropDown              matlab.ui.control.DropDown
        CMRREditField                matlab.ui.control.NumericEditField
        CMRRdBEditFieldLabel         matlab.ui.control.Label
        CommonButton                 matlab.ui.control.Button
        DifferentialButton           matlab.ui.control.Button
        EMGAxes                      matlab.ui.control.UIAxes
        BPAppRevisionEditField       matlab.ui.control.NumericEditField
        BPAppRevisionEditFieldLabel  matlab.ui.control.Label
        BPPCBSerialEditField         matlab.ui.control.NumericEditField
        BPPCBSerialEditFieldLabel    matlab.ui.control.Label
        NodeDropDown                 matlab.ui.control.DropDown
        NodeDropDownLabel            matlab.ui.control.Label
        PMAppRevisionEditField       matlab.ui.control.NumericEditField
        PMAppRevisionLabel           matlab.ui.control.Label
        PMPCBSerialEditField         matlab.ui.control.NumericEditField
        PMPCBSerialEditFieldLabel    matlab.ui.control.Label
    end

    
    properties (Access = public)
        nnp = [];
        node = [];
        nSamp = [];
        plotbuf = [];
        updateTimer = [];
        emgbuf = [];
        emgTimer = [];
        emgSamp =[];
        doUpdate = false;
        pauseTime = 0.01;
        logfileid=[];
        plotcycle=4; %plot every forth cycle, makeing this 5Hz update
        cycle=0;
    end
    
    properties (Access = private)
        updateCount = 1;
        savedSettings = [];
        savedTimeout =[];
        savedPMworInt=[];
        selpath=pwd;% get the current working directory
        LogFileName='TempLogFileName.bin'; % Save this to a temporary file
        LFileID=double([]); % File ID for Log File
       callingFigure=[]; %handle to calling figure        
    end
    
    methods (Access = public)
        
        function updateEMG(app, obj, event)
            resp = app.nnp.read(7, '2053', 3, 'uint8');
            app.emgbuf(1:end-48) = app.emgbuf(49:end);
            if length(resp) == 49
                app.emgbuf(end-48+1:end) = resp(1:48)';
            else
                app.emgbuf(end-48+1:end) = nan(48,1);
            end
            app.EMGAxes.Children.YData = app.emgbuf;
        end
        
        function updateDisplays(app, obj, event)
            
            %Get Accel
            accel=[];
            resp = app.nnp.read(app.node, '2011', 1, 'uint8');
            if length(resp)==4
                if all(resp==255)
                    accel = [Inf Inf Inf];
                    mag = Inf;
                else
                    accel = [0 0 0];
                    for i=1:3
                        accel(i) = (double((bitshift(resp(i), -2))) - 32)/16; %convert to gs for plot
                    end
                    mag = norm(accel);
                end
                app.XEditField.Value = double(resp(1));
                app.YEditField.Value = double(resp(2));
                app.ZEditField.Value = double(resp(3));
                app.CntEditField.Value = double(resp(4));
            end
            
            if ~isempty(accel)
                app.plotbuf(1:end-1,:) = app.plotbuf(2:end,:);
                app.plotbuf(end,:) = [accel'; mag];
                for i = 1:4
                    app.AccelAxes.Children(i).YData = app.plotbuf(:,i);
                    
                end
            end
            
            
            drawnow;
            %pause(app.pauseTime);
            
            switch app.updateCount
                case 1
                    
                    %Get Temp
                    resp = app.nnp.getTemp(app.node);
                    %fprintf('\ntemp %f', resp);
                    if length(resp)==1
                        app.TemperatureEditField.Value = resp;
                        app.TemperatureEditField.BackgroundColor = [1 1 1];
                    else
                        app.TemperatureEditField.Value = Inf;
                        app.TemperatureEditField.BackgroundColor = [.5 .5 .5];
                    end
                    
                case 2
                    
                    %Get Diagnostics VIC
                    resp = double(app.nnp.read(app.node, '3410', 2, 'uint8'));  %VIC
                    %fprintf('\ndiagnostics %f', resp);
                    if length(resp) == 1
                        app.VICEditField.Value = resp;
                        app.VICEditField.BackgroundColor = [1 1 1];
                    else
                        app.VICEditField.Value = Inf;
                        app.VICEditField.BackgroundColor = [.5 .5 .5];
                    end
                case 3
                    
                    %Get Diagnostics VIN
                    resp = double(app.nnp.read(app.node, '3510', 2, 'uint8')); %VIN
                    %fprintf('\ndiagnostics %f', resp);
                    if length(resp) == 1
                        app.VINEditField.Value = resp;
                        app.VINEditField.BackgroundColor = [1 1 1];
                    else
                        app.VINEditField.Value = Inf;
                        app.VINEditField.BackgroundColor = [.5 .5 .5];
                    end
                    
            end
            app.updateCount = app.updateCount+1;
            if app.updateCount == 4
                app.updateCount = 1;
            end
            
            if app.doUpdate
                enable = 'on';
            else
                enable = 'off';
            end
            
            
            app.PMVNETEditField.Enable = enable;
            app.PMPCBSerialEditField.Enable = 'off';
            app.PMAppRevisionEditField.Enable = 'off';
            app.BPPCBSerialEditField.Enable = 'off';
            app.BPAppRevisionEditField.Enable = 'off';
            
            
            if ~app.doUpdate
                return
            end
            
            %pause(app.pauseTime);
            
            %Get PM VNET setting
            resp = app.nnp.getVNET();
            %fprintf('\nvnet %f', resp);
            if length(resp)==1
                app.PMVNETEditField.Value = resp;
                app.PMVNETEditField.BackgroundColor = [1 1 1];
            else
                app.PMVNETEditField.Value = Inf;
                app.PMVNETEditField.BackgroundColor = [.5 .5 .5];
                
            end
            
            app.doUpdate = false;
        end
        
        
        function updateEMGfile(app, obj, event)
            resp = app.nnp.read(7, '2053', 3, 'uint8');
            app.emgbuf(1:end-48) = app.emgbuf(49:end);
            if length(resp) == 49
                newdata = resp(1:48)';
            else
                newdata = nan(48,1); % 
            end
            app.emgbuf(end-48+1:end) = newdata;
            % If I update every cycle, then I am updating the plot at 20
            % Hz.  Could probably update slower, like 10 or 5 hz.  To do
            % that I could have a running counter to determine when to
            % update. 
            if app.cycle>=app.plotcycle
            app.EMGAxes.Children.YData = app.emgbuf; %update plot
            app.cycle=0;
            else
                app.cycle=app.cycle+1; %increment cycle and skip update
            end
            fwrite(app.LFileID,newdata,'single'); % log newdata to single to preserve nan and inf
        end
        
        function results = NewLogFileName(app)
            userstring=sprintf('P%d_',int16(app.UserEditField.Value));
            timestring=sprintf('_%02.0f',datevec(now));
            %
            app.LogFilenameEditField.Value=[userstring,'RawEMG',timestring,'.bin'];
            %
            app.LogFileName=fullfile(app.FolderEditField.Value,app.LogFilenameEditField.Value);
        end
        
        function results = NewLogFolderName(app)
            newpath = uigetdir(app.selpath,'Folder to store EMG files');
            if ischar(newpath) % if canceled, this is a number 0
                if isfolder(newpath)  % can only use this with character array
                    app.selpath=newpath;  % update the path if a new one given
                    app.FolderEditField.Value=app.selpath;
                    app.FolderButton.BackgroundColor=[0.96,0.96,0.96]; % from [1,.9,.6]
                end
            end
            figure(app.BP2CProductionTestUIFigure); % Restore focus to this window
            %
        end
    end
    
    methods (Access = private)
        
        function restoreRadio(app)
            app.nnp.setRadioSettings(app.savedSettings);
            app.nnp.timeout = app.savedTimeout;
            if app.savedPMworInt > 0
                app.nnp.worOn(app.savedPMworInt); %reenable WOR on PM if it was enabled
            end
        end
        
        function saveRadio(app)
            %read back AP settings
            settings = app.nnp.getRadioSettings();
            if isempty(settings)
                msgbox('could not read back AccessPoint Settings','Radio Configuration', 'error');
                return;
            else
                app.savedSettings = settings;
            end
            app.savedTimeout = app.nnp.timeout;
            
            %is PM WOR enabled?
            resp = double(app.nnp.read(7, '2600', 9, 'uint16'));
            if length(resp)==1
                app.savedPMworInt = resp;
            else
                msgbox('could not determine if PM has WOR enabled, will get set to default 20ms');
                app.savedPMworInt = 20;
            end
        end
        
        function startEMG(app)
            app.enableAll('off');
            app.saveRadio();
            %for max bandwidth, we need to eliminate radio retries, shorten timeouts, and turn of WOR
            app.nnp.setRadio('timeout', 10, 'retries', 0');
            app.nnp.worOff();
            app.nnp.timeout = 0.04;
            
            app.EMGAxes.Color = [1 1 1];
            
            %Set diagnostic Mode and channel
            app.ChannelDropDown.Enable = 'off';
            app.GainDropDown.Enable = 'off';
            switch app.ChannelDropDown.Value
                case 'Channel 1'
                    ch=1;
                    mode=0;
                case 'Channel 2'
                    ch=2;
                    mode = 0;
                case 'Offset 1'
                    ch=1;
                    mode=2;
                case 'Offset 2'
                    ch=2;
                    mode = 2;
                otherwise
                    error('unknown dropdown value')
            end
            
            if app.nnp.write(app.node, '3000', 1, mode, 'uint8')~=0
                msgbox(['enter diagnostic mode ', num2str(mode), ' may have failed'])
            end
            
            if ~app.nnp.enterTestRaw(app.node, ch)
                msgbox('Could not enter Raw MES mode')
            end
            start(app.emgTimer); %not log data to file
        end
        
        function stopEMG(app)
            app.enableAll('on');
            stop(app.emgTimer);
            app.ChannelDropDown.Enable = 'on';
            app.GainDropDown.Enable = 'on';
            app.restoreRadio();
            if ~app.nnp.enterWaiting()
                msgbox('Could not enter waiting mode')
            end
        end
        
        function [amp, freq, baseline, sat] = calcFFT(app)
            Fs = 1000;            % Sampling frequency
            T = 1/Fs;             % Sampling period
            L = app.emgSamp;             % Length of signal
            t = (0:L-1)*T;        % Time vector
            r = 3;                % frequency tolerance around max freq (in Hz)
            
            good = ~isnan(app.emgbuf);
            
            if all(~good)
                msgbox('No EMG data', 'Data Warning', 'warn')
                amp = Inf;
                freq = Inf;
                baseline = Inf;
                sat = true;
                return;
            elseif any(~good)
                igood = find(diff([0; good; 0])==-1);  %transition from good to bad
                ibad = find(diff([0; good; 0])==1);  %transition from bad to good
                
                [L, j] = max(igood - ibad); %new Length of signal
                
                i = igood(j) - L; %starting index in app.emgbuf of longest non-nan section
                x = app.emgbuf(i:i+L-1);
                msgbox(sprintf('Empty sections were found in EMG data. Calculating outputs based on %u samples instead of %u',L, app.emgSamp), 'Data Warning', 'warn')
            else
                x=app.emgbuf;
            end
            Y = fft(x);
            P2 = abs(Y/L);
            P1 = P2(1:L/2+1);
            P1(2:end-1) = 2*P1(2:end-1);
            
            baseline = round(P1(1));
            
            [~, freq] = max(P1(2:end));
            
            if freq > r
                ampRaw = sum(P1(freq-r+1:freq+r+1));  %use max freq+/-3Hz
            else
                ampRaw = 0;
            end
            
            if ampRaw > 255
                sat = true;
                app.EMGAxes.Color = [1 .7 .7];
            else
                sat = false;
            end
            
            switch str2double(app.GainDropDown.Value)
                case 4,  gain = 300;
                case 11, gain = 1500;
                otherwise, msgbox('gain')
            end
            amp = ampRaw*3.3/256/gain*1000; %amplitude as Peak to Peak in mV


            if app.ShowFFTCheckBox.Value
                f = Fs*(0:(L/2))/L;
                figure;
                plot(f, P1);
                title('Single-Sided Amplitude Spectrum of EMG')
                xlabel('f (Hz)')
                ylabel('EMG Power (ADU)')
            end
        end
        
        function calcCMRR(app)
            if isfinite(app.DifferentialAmpEditField.Value) && isfinite(app.CommonAmpEditField.Value)
                app.CMRREditField.Value = 20*log10(app.DifferentialAmpEditField.Value/(app.CommonAmpEditField.Value/1000));
            end
        end
        
        function enableAll(app, enable)
            app.ViewEMGButton.Enable = enable;
            app.StartButton.Enable = enable;
            app.DifferentialButton.Enable = enable;
            app.CommonButton.Enable = enable;
            app.NodeDropDown.Enable = enable;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin<2
                NNP=[];
            else
                NNP=varargin{1};
            end
            if isempty(NNP)
                app.nnp = NNPAPI();
                if isempty(app.nnp.port)
                    msgbox('No port chosen for NNP Access Point')
                    delete(app.nnp)
                    return
                end
            else
                app.nnp = NNP;
            end
            if nargin>2
                app.callingFigure=varargin{2};
            end


            app.PMPCBSerialEditField.Enable = 'on';
            app.PMAppRevisionEditField.Enable = 'on';
            
            %Get PM App Revs
            resp = app.nnp.getSwRev(7);
            if length(resp)==1
                app.PMAppRevisionEditField.Value = resp(1);
                app.PMAppRevisionEditField.BackgroundColor = [1 1 1];
            else
                app.PMAppRevisionEditField.Value = Inf;
                app.PMAppRevisionEditField.BackgroundColor = [1 0 0];
            end
            
            %pause(app.pauseTime);
            
            %Get PM Serial number
            resp = app.nnp.getSerial(7);
            if length(resp)==1
                app.PMPCBSerialEditField.Value = resp(1);
                app.PMPCBSerialEditField.BackgroundColor = [1 1 1];
            else
                app.PMPCBSerialEditField.Value = Inf;
                app.PMPCBSerialEditField.BackgroundColor = [1 0 0];
            end
            
            %pause(app.pauseTime);
            
            if ~app.nnp.enterWaiting()
                msgbox('could not enter waiting on PM');
                return;
            end
            
            %pause(app.pauseTime);
            
            if ~app.nnp.setVNET(7.5)
                msgbox('could not set PM VNET');
                return;
            end
            
            %pause(app.pauseTime);
            
            if ~app.nnp.networkOn()
                msgbox('could not turn on network');
                return;
            end
            
            pause(0.2)
            while app.nnp.getNetworkStatus()~=1
                resp = questdlg('Could not confirm network status, Retry?');
                if isequal(resp, 'No')
                    return;
                end
            end
            
            app.VNETVINCheckButton.Enable = 'off';
            
            app.nSamp = 100;
            app.plotbuf = zeros(app.nSamp,4);
            plot(app.AccelAxes, 1:app.nSamp, app.plotbuf);
            app.AccelAxes.XLim = [0 app.nSamp];
            app.AccelAxes.YLim = [-2 2];
            app.AccelAxes.Children(1).Color = [1 0 0]; %X=red
            app.AccelAxes.Children(2).Color = [0 .5 0];%Y=green
            app.AccelAxes.Children(3).Color = [0 0 1]; %Z=blue
            app.AccelAxes.Children(4).Color = [0 0 0]; %Mag=black, dotted
            app.AccelAxes.Children(4).LineStyle =':';
            
            app.AccelAxes.Toolbar.Visible = 'off';  %to make the little toolbar at top right disappear.
            disableDefaultInteractivity(app.AccelAxes); %to prevent clicking points with cursor
            
            app.emgSamp = 1000;
            app.emgbuf = nan(app.emgSamp, 1);
            plot(app.EMGAxes, 1:app.emgSamp, app.emgbuf);
            app.EMGAxes.XLim = [0 1000];
            app.EMGAxes.YLim = [0 255];

            app.EMGAxes.Toolbar.Visible = 'off';  %to make the little toolbar at top right disappear.
            disableDefaultInteractivity(app.EMGAxes); %to prevent clicking points with cursor
 
            pause(0.1);
            app.NodeDropDownValueChanged();
            
            app.updateTimer = timer();
            app.updateTimer.Period = 0.25;
            app.updateTimer.ExecutionMode = 'fixedRate';
            app.updateTimer.BusyMode = 'drop';
            app.updateTimer.TimerFcn = @app.updateDisplays;
            
            
            app.emgTimer = timer();
            app.emgTimer.Period = 0.048;
            app.emgTimer.ExecutionMode = 'fixedRate';
            app.emgTimer.BusyMode = 'drop';
            app.emgTimer.TimerFcn = @app.updateEMG;
%            app.emgTimer.TimerFcn = @app.updateEMGfile;
            app.FolderEditField.Value=app.selpath;  % start with current path
        end

        % Value changed function: NodeDropDown
        function NodeDropDownValueChanged(app, event)
            app.node = str2double(app.NodeDropDown.Value);
            
            
            app.BPPCBSerialEditField.Enable = 'on';
            app.BPAppRevisionEditField.Enable = 'on';
            
            %Check module type
            resp = app.nnp.getType(app.node);
            if ~isempty(resp) && ~isequal(resp, '2ch Biopotential Sensor BP-2')
                msgbox('Connected device is not a BP', 'BP Test', 'warn');
            end
            
            %Get RM App Revs
            resp = app.nnp.getSwRev(app.node);
            if length(resp)==1
                app.BPAppRevisionEditField.Value = resp;
                app.BPAppRevisionEditField.BackgroundColor = [1 1 1];
            else
                app.BPAppRevisionEditField.Value = Inf;
                app.BPAppRevisionEditField.BackgroundColor = [1 0 0];
            end
            
            %pause(app.pauseTime);
            
            %Get RM Serial number
            resp = app.nnp.getSerial(app.node);
            if length(resp)==1
                app.BPPCBSerialEditField.Value = resp;
                app.BPPCBSerialEditField.BackgroundColor = [1 1 1];
            else
                app.BPPCBSerialEditField.Value = Inf;
                app.BPPCBSerialEditField.BackgroundColor = [1 0 0];
            end


            %pause(app.pauseTime);
            
            
        end

        % Close request function: BP2CProductionTestUIFigure
        function BP2CProductionTestUIFigureCloseRequest(app, event)
            if ~isempty(app.updateTimer)
                if isvalid(app.updateTimer)
                    stop(app.updateTimer);
                end
                delete(app.updateTimer);
            end
            if ~isempty(app.emgTimer)
                if isvalid(app.emgTimer)
                    stop(app.emgTimer);
                end
                delete(app.emgTimer);
            end
            if ishandle(app.callingFigure) % check that is is valid
                figure(app.callingFigure); % bring to front
            end

            delete(app);
        end

        % Callback function
        function reinitializeButtonPushed(app, event)
            delete(app.nnp);
            app.startupFcn();
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            
            
            %Set diagnostic Mode
            if app.nnp.write(app.node, '3000', 1, 1, 'uint8')~=0
                msgbox('enter diagnostic mode 1 may have failed')
            end
            
            if app.StartButton.Value == 1
                app.enableAll('off');
                app.StartButton.Enable = 'on';
                if ~isempty(app.updateTimer)
                    app.StartButton.Text = 'Running. Press to Stop';
                    drawnow;
                    
                    app.doUpdate = true;
                    start(app.updateTimer)
                    app.VNETVINCheckButton.Enable = 'on';
                    return;
                else
                    msgbox('no timer to start')
                    
                end
            else
                app.enableAll('on');
                if ~isempty(app.updateTimer)
                    stop(app.updateTimer)
                else
                    msgbox('no timer to stop')
                end
            end
            pause(0.5);
            app.StartButton.Text = 'Start';
            app.VNETVINCheckButton.Enable = 'off';
            drawnow;
            
            %set gain to default
            app.nnp.setBPGains(app.node, 11, 11)
            
            
            [ch1, ch2] = app.nnp.getBPGains(app.node);
            if ch1 ~= ch2 || ch1 ~= 11
                msgbox('Gains not set')
            end
            
            %app.StartButton.Value = 0;
            
            
        end

        % Value changed function: VNETVINCheckButton
        function VNETVINCheckButtonValueChanged(app, event)
            if app.VNETVINCheckButton.Value
                if ~app.nnp.setVNET(8.5)
                    msgbox('action may have failed')
                end
            else
                if ~app.nnp.setVNET(7.5)
                    msgbox('action may have failed')
                end
            end
            app.doUpdate = true;
        end

        % Value changed function: ViewEMGButton
        function ViewEMGButtonValueChanged(app, event)
            if app.ViewEMGButton.Value
                app.startEMG();
                app.ViewEMGButton.Enable = 'on';
                app.GainDropDown.Enable = 'on'; % allow gain changes while viewing
            else
                app.stopEMG();
            end
        end

        % Value changed function: GainDropDown
        function GainDropDownValueChanged(app, event)
            gain = str2double(app.GainDropDown.Value);
            if ~app.nnp.setBPGains(app.node, gain, gain)
                msgbox('Gains not set')
            end

        end

        % Button pushed function: DifferentialButton
        function DifferentialButtonPushed(app, event)
            app.DifferentialButton.BackgroundColor = [0 1 0];
            app.startEMG();
            pause(1.5)
            app.stopEMG();
            app.DifferentialButton.BackgroundColor = [.96 .96 .96];
            [amp, freq, baseline] = app.calcFFT();
            app.DifferentialAmpEditField.Value = amp;
            app.FrequencyEditField.Value = freq;
            app.BaselineEditField.Value = baseline;
            calcCMRR(app);
        end

        % Button pushed function: CommonButton
        function CommonButtonPushed(app, event)
            app.CommonButton.BackgroundColor = [0 1 0];
            app.startEMG();
            pause(1.5)
            app.stopEMG();
            app.CommonButton.BackgroundColor = [.96 .96 .96];
            [amp, freq, baseline] = app.calcFFT();
            app.CommonAmpEditField.Value = amp;
            app.FrequencyEditField.Value = freq;
            app.BaselineEditField.Value = baseline;
            calcCMRR(app);
        end

        % Button pushed function: FolderButton
        function FolderButtonPushed(app, event)
            NewLogFolderName(app);
            NewLogFileName(app);
        end

        % Button pushed function: NewLogFile
        function NewLogFileButtonPushed(app, event)
            % if current fle is open, want to close it first
            if exist('app.LFileID','var')==1  %file id exists
                if ~isempty(fopen(app.LFileID)) %file is open
                    fclose(app.LFileID); % close file
                end
            end
            % Get new file name
            NewLogFileName(app);
            % Open new file
            if exist('app.LFileID','var')==1  %file id exists
                if isempty(fopen(app.LFileID)) %file is not open
                    app.LFileID=fopen(app.LogFileName,'a'); % open file in append mode
                end
            else
                app.LFileID=fopen(app.LogFileName,'a'); % open file in append mode
            end
        end

        % Value changed function: LogEMGSwitch
        function LogEMGSwitchValueChanged(app, event)
            value = app.LogEMGSwitch.Value;
            if value=="On"
                app.emgTimer.TimerFcn = @app.updateEMGfile;
                %                 app.LogEMGSwitch.BackgroundColor='g';
                % add a check here for a valid folder and call
                % FolderButtonPushed if not
                if ~isfolder(app.FolderEditField.Value)
                    NewLogFolderName(app);
                end
                % Add check for valid file FID, and see if it is open
                % if not, then check for valid filename and create on if
                % not and open the file in append mode.
                if ~isfile(app.LogFileName)
                    NewLogFileName(app);    % create file name
                end
                % check whether file ID exists
                if exist('app.LFileID','var')==0
                    app.LFileID=fopen(app.LogFileName,'a'); % open file in append mode
                end
                % check whether file is already open
                if isempty(fopen(app.LFileID)) %file is not open
                    app.LFileID=fopen(app.LogFileName,'a'); % open file in append mode
                end
                % not create header
            else
                app.emgTimer.TimerFcn = @app.updateEMG; % this is set at startup
                %                 app.LogEMGSwitch.BackgroundColor=[.96,.96,.96];
                
                % Note that I don't close the file here because I may want
                % to append to it.
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BP2CProductionTestUIFigure = uifigure('Visible', 'off');
            app.BP2CProductionTestUIFigure.Name = 'BP2C Production Test';
            app.BP2CProductionTestUIFigure.CloseRequestFcn = createCallbackFcn(app, @BP2CProductionTestUIFigureCloseRequest, true);
            app.PMPCBSerialEditFieldLabel = uilabel(app.BP2CProductionTestUIFigure);
            app.PMPCBSerialEditFieldLabel.Text = 'PM PCB Serial #';
            app.PMPCBSerialEditField = uieditfield(app.BP2CProductionTestUIFigure, 'numeric');
            app.PMPCBSerialEditField.Editable = 'off';
            app.PMPCBSerialEditField.Value = Inf;
            app.PMAppRevisionLabel = uilabel(app.BP2CProductionTestUIFigure);
            app.PMAppRevisionLabel.Text = 'PM App Revision';
            app.PMAppRevisionEditField = uieditfield(app.BP2CProductionTestUIFigure, 'numeric');
            app.PMAppRevisionEditField.Editable = 'off';
            app.PMAppRevisionEditField.Value = Inf;
            app.NodeDropDownLabel = uilabel(app.BP2CProductionTestUIFigure);
            app.NodeDropDownLabel.Text = 'Node';
            app.NodeDropDown = uidropdown(app.BP2CProductionTestUIFigure);
            app.NodeDropDown.Items = {'1', '2', '3', '4', '5', '6', '9', '10'};
            app.NodeDropDown.ValueChangedFcn = createCallbackFcn(app, @NodeDropDownValueChanged, true);
            app.NodeDropDown.Value = '9';
            app.BPPCBSerialEditFieldLabel = uilabel(app.BP2CProductionTestUIFigure);
            app.BPPCBSerialEditFieldLabel.Text = 'BP PCB Serial #';
            app.BPPCBSerialEditField = uieditfield(app.BP2CProductionTestUIFigure, 'numeric');
            app.BPPCBSerialEditField.Editable = 'off';
            app.BPPCBSerialEditField.Value = Inf;
            app.BPAppRevisionEditFieldLabel = uilabel(app.BP2CProductionTestUIFigure);
            app.BPAppRevisionEditFieldLabel.Text = 'BP App Revision';
            app.BPAppRevisionEditField = uieditfield(app.BP2CProductionTestUIFigure, 'numeric');
            app.BPAppRevisionEditField.Editable = 'off';
            app.BPAppRevisionEditField.Value = Inf;
            app.PanelEMG = uipanel(app.BP2CProductionTestUIFigure);
            app.EMGAxes = uiaxes(app.PanelEMG);
            title(app.EMGAxes, 'Raw EMG')
            xlabel(app.EMGAxes, 'time (ms)')
            ylabel(app.EMGAxes, 'EMG (ADU)')
            app.DifferentialButton = uibutton(app.PanelEMG, 'push');
            app.DifferentialButton.ButtonPushedFcn = createCallbackFcn(app, @DifferentialButtonPushed, true);
            app.DifferentialButton.Text = 'Differential';
            app.CommonButton = uibutton(app.PanelEMG, 'push');
            app.CommonButton.ButtonPushedFcn = createCallbackFcn(app, @CommonButtonPushed, true);
            app.CommonButton.Text = 'Common';
            app.CMRRdBEditFieldLabel = uilabel(app.PanelEMG);
            app.CMRRdBEditFieldLabel.Text = 'CMRR (dB)';
            app.CMRREditField = uieditfield(app.PanelEMG, 'numeric');
            app.CMRREditField.Editable = 'off';
            app.CMRREditField.Value = Inf;
            app.ChannelDropDown = uidropdown(app.PanelEMG);
            app.ChannelDropDown.Items = {'Channel 1', 'Channel 2', 'Offset 1', 'Offset 2'};
            app.ChannelDropDown.Value = 'Channel 1';
            app.GainDropDown = uidropdown(app.PanelEMG);
            app.GainDropDown.Items = {'Gain 300x', 'Gain 1500x'};
            app.GainDropDown.ItemsData = {'4', ' 11'};
            app.GainDropDown.ValueChangedFcn = createCallbackFcn(app, @GainDropDownValueChanged, true);
            app.GainDropDown.Value = ' 11';
            app.VPPRTImVLabel = uilabel(app.PanelEMG);
            app.VPPRTImVLabel.Text = 'VPP RTI (mV)';
            app.DifferentialAmpEditField = uieditfield(app.PanelEMG, 'numeric');
            app.DifferentialAmpEditField.Editable = 'off';
            app.DifferentialAmpEditField.Value = Inf;
            app.CommonAmpEditField = uieditfield(app.PanelEMG, 'numeric');
            app.CommonAmpEditField.Editable = 'off';
            app.CommonAmpEditField.Value = Inf;
            app.ShowFFTCheckBox = uicheckbox(app.PanelEMG);
            app.ShowFFTCheckBox.Text = 'Show FFT';
            app.ViewEMGButton = uibutton(app.PanelEMG, 'state');
            app.ViewEMGButton.ValueChangedFcn = createCallbackFcn(app, @ViewEMGButtonValueChanged, true);
            app.ViewEMGButton.Text = 'View EMG';
            app.FrequencyHzEditFieldLabel = uilabel(app.PanelEMG);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.FrequencyEditField = uieditfield(app.PanelEMG, 'numeric');
            app.FrequencyEditField.Editable = 'off';
            app.FrequencyEditField.Value = Inf;
            app.CommonModeInputsignalexpectedtobe1000xDifferentialLabel = uilabel(app.PanelEMG);
            app.CommonModeInputsignalexpectedtobe1000xDifferentialLabel.Text = 'Common Mode Input signal expected to be 1000x Differential Input Signal';
            app.BaselineADULabel = uilabel(app.PanelEMG);
            app.BaselineADULabel.Text = 'Baseline (ADU)';
            app.BaselineEditField = uieditfield(app.PanelEMG, 'numeric');
            app.BaselineEditField.Editable = 'off';
            app.BaselineEditField.Value = Inf;
            app.PanelAccel = uipanel(app.BP2CProductionTestUIFigure);
            app.AccelAxes = uiaxes(app.PanelAccel);
            title(app.AccelAxes, 'Accelerometer')
            xlabel(app.AccelAxes, 'sample')
            ylabel(app.AccelAxes, 'acceleration (g''s)')
            app.StartButton = uibutton(app.PanelAccel, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.VNETVINCheckButton = uibutton(app.PanelAccel, 'state');
            app.VNETVINCheckButton.ValueChangedFcn = createCallbackFcn(app, @VNETVINCheckButtonValueChanged, true);
            app.VNETVINCheckButton.Text = 'VNET/VIN Check';
            app.TemperatureCEditFieldLabel = uilabel(app.PanelAccel);
            app.TemperatureCEditFieldLabel.Text = 'Temperature (°C)';
            app.TemperatureEditField = uieditfield(app.PanelAccel, 'numeric');
            app.TemperatureEditField.Editable = 'off';
            app.TemperatureEditField.Value = Inf;
            app.VINADUEditFieldLabel = uilabel(app.PanelAccel);
            app.VINADUEditFieldLabel.Text = 'VIN (ADU)';
            app.VINEditField = uieditfield(app.PanelAccel, 'numeric');
            app.VINEditField.Editable = 'off';
            app.VINEditField.Value = Inf;
            app.VICADUEditFieldLabel = uilabel(app.PanelAccel);
            app.VICADUEditFieldLabel.Text = 'VIC (ADU)';
            app.VICEditField = uieditfield(app.PanelAccel, 'numeric');
            app.VICEditField.Editable = 'off';
            app.VICEditField.Value = Inf;
            app.PMVNETVEditFieldLabel = uilabel(app.PanelAccel);
            app.PMVNETVEditFieldLabel.Text = 'PM VNET (V)';
            app.PMVNETEditField = uieditfield(app.PanelAccel, 'numeric');
            app.PMVNETEditField.Editable = 'off';
            app.PMVNETEditField.Value = Inf;
            app.XLabel = uilabel(app.PanelAccel);
            app.XLabel.Text = 'X';
            app.XEditField = uieditfield(app.PanelAccel, 'numeric');
            app.XEditField.Editable = 'off';
            app.XEditField.Value = Inf;
            app.YLabel = uilabel(app.PanelAccel);
            app.YLabel.Text = 'Y';
            app.YEditField = uieditfield(app.PanelAccel, 'numeric');
            app.YEditField.Editable = 'off';
            app.YEditField.Value = Inf;
            app.ZLabel = uilabel(app.PanelAccel);
            app.ZLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.PanelAccel, 'numeric');
            app.ZEditField.Editable = 'off';
            app.ZEditField.Value = Inf;
            app.EditFieldLabel = uilabel(app.PanelAccel);
            app.EditFieldLabel.Text = '#';
            app.CntEditField = uieditfield(app.PanelAccel, 'numeric');
            app.CntEditField.Editable = 'off';
            app.CntEditField.Value = Inf;
            app.LogEMGLabel = uilabel(app.BP2CProductionTestUIFigure);
            app.LogEMGLabel.Text = 'LogEMG';
            app.LogEMGSwitch = uiswitch(app.BP2CProductionTestUIFigure, 'slider');
            app.LogEMGSwitch.ValueChangedFcn = createCallbackFcn(app, @LogEMGSwitchValueChanged, true);
            app.LogFilenameEditField = uieditfield(app.BP2CProductionTestUIFigure, 'text');
            app.LogFilenameEditField.Editable = 'off';
            app.FolderButton = uibutton(app.BP2CProductionTestUIFigure, 'push');
            app.FolderButton.ButtonPushedFcn = createCallbackFcn(app, @FolderButtonPushed, true);
            app.FolderButton.Text = 'Folder';
            app.FolderEditField = uieditfield(app.BP2CProductionTestUIFigure, 'text');
            app.FolderEditField.Editable = 'off';
            app.NewLogFile = uibutton(app.BP2CProductionTestUIFigure, 'push');
            app.NewLogFile.ButtonPushedFcn = createCallbackFcn(app, @NewLogFileButtonPushed, true);
            app.NewLogFile.Text = 'New File';
            app.UserEditFieldLabel = uilabel(app.BP2CProductionTestUIFigure);
            app.UserEditFieldLabel.Text = 'User';
            app.UserEditField = uieditfield(app.BP2CProductionTestUIFigure, 'numeric');
            app.UserEditField.ValueDisplayFormat = '%d';
            app.UserEditField.Value = 68;
            % Show the figure after all components are created
            app.BP2CProductionTestUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bptestf(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BP2CProductionTestUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BP2CProductionTestUIFigure)
        end
    end
end