classdef burnintest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BurnInTestUIFigure       matlab.ui.Figure
        BurnInSetupListBox       matlab.ui.control.ListBox
        BurnInSetupListBoxLabel  matlab.ui.control.Label
    end


    properties (Access = public)
        nnp = [];
        savedSettings = [];
    end

    methods (Access = private)
    
        function out = abort(app, str)
            resp = questdlg(['Failed on ', str, '. Retry?'], 'Action Failed');
            if isequal(resp, 'No')
                out = true;
            else
                out = false;
            end
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, NNP)
            if isempty(NNP)
                app.nnp = NNPAPI();
                if isempty(app.nnp.port)
                    msgbox('No port chosen for NNP Access Point')
                    delete(app.nnp)
                    return
                end
            else
                app.nnp = NNP;
            end
            
            [filename, pathname] = uigetfile('*.txt', 'Select Burn_in Configuration File', 'MultiSelect', 'off');
                if isempty(pathname) || isempty(filename) || ~ischar(filename) || ~ischar(pathname)
                    msgbox('No file chosen', 'Burn In Test', 'error');  
                    return;
                else
                    fid = fopen([pathname filename]);
                    config = textscan(fid,'%s %f %f %f %f %f %f %f %f %f %f', 'CollectOutput', true);
                    fclose(fid);
                end
                nodes = config{2};
                types = config{1};
                
                nRows = size(types, 1);
                if size(nodes,1) < nRows
                    nodes(nRows,:) = nan(1,10);
                end
                
                iPM = cellfun(@(x) isequal(x, 'PM'), types);
                iPG = cellfun(@(x) isequal(x, 'PG'), types);
                iBP = cellfun(@(x) isequal(x, 'BP'), types);

                PMchans = nodes(iPM,1); %only one PM per row
                PGnodes = nodes(iPG,:);
                BPnodes = nodes(iBP,:);
                
                burnin = struct([]);
                for i=1:length(PMchans)
                    burnin(i).PM = PMchans(i);
                    burnin(i).PG = PGnodes(i,~isnan(PGnodes(i,:)));
                    burnin(i).BP = BPnodes(i,~isnan(BPnodes(i,:)));
                end
                
                app.savedSettings = app.nnp.getRadioSettings();
                
                for i = 1:length(burnin)
                    pm = burnin(i).PM;
                    app.nnp.setRadio('chan', pm) % set radio
                    
                    while true
                        sn = app.nnp.getSerial(7);
                        if isempty(sn)
                            str = sprintf('PM: %u (#???)', pm);
                            if app.abort(sprintf('Get PM Serial on radio channel %u', pm))
                                return;
                            end
                        else
                            str = sprintf('PM: %u (#%03u)', pm, sn);
                            break;
                        end
                    end
                   
                    while ~app.nnp.enterWaiting()
                       if app.abort('Enter Waiting') 
                           return;
                       end
                    end
                    
                    while ~app.nnp.setVNET(9.5)
                       if app.abort('Set Network Voltage') 
                           return;
                       end
                    end
                                        
                    while ~app.nnp.networkOn()
                       if app.abort('Turn on Network') 
                           return;
                       end
                    end
                    pause(0.2);
                    while app.nnp.getNetworkStatus()~=1
                        if app.abort('Confirm Network Status')
                            return;
                        end
                    end
                    
                    if ~isempty(burnin(i).PG)  
                        str = [str, ' - PG: '];
                        
                        for node=burnin(i).PG
                            while true
                                sn = app.nnp.getSerial(node);
                                if isempty(sn)
                                    str = [str, sprintf('%u (#???), ',node)];
                                    if app.abort(sprintf('Get PG Node %u Serial Number on PM radio channel %u', node, pm))
                                         return;
                                    end
                                else
                                    str = [str, sprintf('%u (#%03u), ',node, sn)];
                                    break;
                                end
                            end
                            
                            %set full stim
                            for ch=1:4
                                while true
                                    resp = app.nnp.write(node, '3212', ch, uint8([255 200]), 'uint8');
                                    if length(resp)~=1 || resp~=0
                                       if app.abort(sprintf('Set Stim Values on Node %u on PM radio channel %u', node, pm))
                                           return;
                                       end
                                    else
                                        resp = app.nnp.read(node, '3212', ch, 'uint8');

                                        if length(resp)~=2 || ~all(resp==[255 200]) 
                                            if app.abort(sprintf('Confirm Stim Values on Node %u on PM radio channel %u', node, pm) )
                                                 return;
                                            end
                                        else
                                            break;
                                        end
                                    end
                                end
                            end
                        end
                        str = str(1:end-2); %strip final comma/space
                    end
                    
                    if ~isempty(burnin(i).BP)  
                        str = [str, ' - BP: '];

                        for node = burnin(i).BP
                            while true
                                sn = app.nnp.getSerial(node);

                                if isempty(sn)
                                    str = [str, sprintf('%u (#???), ',node)];
                                    if app.abort(sprintf('Get BP Serial Number on Node %u on PM radio channel %u', node, pm))  
                                        return;
                                    end
                                else
                                    str = [str, sprintf('%u (#%03u), ',node, sn)];
                                    break;
                                end
                            end
    
                        end
                        str = str(1:end-2); %strip final comma/space
                    end
                    
                    app.BurnInSetupListBox.Items{i} = str;
                
                
                    while ~app.nnp.enterTestStim()
                        if app.abort(sprintf('Enter Test Stim on PM radio channel %u', pm)) 
                            return;
                        end
                    end
                end


                app.nnp.setRadioSettings(app.savedSettings);
                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BurnInTestUIFigure = uifigure('Visible', 'off');
            app.BurnInTestUIFigure.Name = 'Burn In Test';
            app.BurnInSetupListBoxLabel = uilabel(app.BurnInTestUIFigure);
            app.BurnInSetupListBoxLabel.Text = 'Burn In Setup';
            app.BurnInSetupListBox = uilistbox(app.BurnInTestUIFigure);
            app.BurnInSetupListBox.Items = {};
            app.BurnInSetupListBox.Value = {};
            % Show the figure after all components are created
            app.BurnInTestUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = burnintest(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BurnInTestUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BurnInTestUIFigure)
        end
    end
end