classdef chanProfiler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        FileMenu                 matlab.ui.container.Menu
        NewMenu                  matlab.ui.container.Menu
        OpenMenu                 matlab.ui.container.Menu
        SaveMenu                 matlab.ui.container.Menu
        SaveAsMenu               matlab.ui.container.Menu
        ExportMenu               matlab.ui.container.Menu
        XMLMenu                  matlab.ui.container.Menu
        ImportMenu               matlab.ui.container.Menu
        fromSubwayDiagramMenu    matlab.ui.container.Menu
        HelpMenu                 matlab.ui.container.Menu
        NumPadHelpMenu           matlab.ui.container.Menu
        NumPadHelpPanel          matlab.ui.container.Panel
        numpadimage              matlab.ui.control.Image
        FlagButton               matlab.ui.control.StateButton
        HelpMessagesButton       matlab.ui.control.StateButton
        HelpPanel                matlab.ui.container.Panel
        HelpOverlayImage         matlab.ui.control.Image
        HelpLabel                matlab.ui.control.Label
        HelpImage                matlab.ui.control.Image
        SystemImageButton        matlab.ui.control.StateButton
        Panel                    matlab.ui.container.Panel
        UserIDEditField          matlab.ui.control.EditField
        TypeDropDown             matlab.ui.control.DropDown
        AmpDownButton            matlab.ui.control.Button
        AmpUpButton              matlab.ui.control.Button
        PullfromTablePanel       matlab.ui.container.Panel
        ThresholdPullButton      matlab.ui.control.Button
        MaximumPullButton        matlab.ui.control.Button
        MuscleEditField          matlab.ui.control.EditField
        NextChButton             matlab.ui.control.Button
        PrevChButton             matlab.ui.control.Button
        StimOFFButton            matlab.ui.control.Button
        StimONButton             matlab.ui.control.Button
        PWusSliderLabel          matlab.ui.control.Label
        PWUpButton               matlab.ui.control.Button
        PWDnButton               matlab.ui.control.Button
        PWEditField              matlab.ui.control.NumericEditField
        NodeEditField            matlab.ui.control.NumericEditField
        NodeEditFieldLabel       matlab.ui.control.Label
        ChannelEditField         matlab.ui.control.NumericEditField
        ChannelEditFieldLabel    matlab.ui.control.Label
        PWUpBigButton            matlab.ui.control.Button
        PWDnBigButton            matlab.ui.control.Button
        AmplitudeLabel           matlab.ui.control.Label
        PAEditField              matlab.ui.control.NumericEditField
        SavetoTablePanel         matlab.ui.container.Panel
        ThreshButton             matlab.ui.control.Button
        MaxButton                matlab.ui.control.Button
        ZeroPWButton             matlab.ui.control.Button
        SystemImagePanel         matlab.ui.container.Panel
        Image                    matlab.ui.control.Image
        DebugPanel               matlab.ui.container.Panel
        OverrideNumlockCheckBox  matlab.ui.control.CheckBox
        respEditField            matlab.ui.control.EditField
        respEditFieldLabel       matlab.ui.control.Label
        KeyEditField             matlab.ui.control.EditField
        KeyEditFieldLabel        matlab.ui.control.Label
        ErrorEditField           matlab.ui.control.EditField
        ErrorLamp                matlab.ui.control.Lamp
        ErrorLampLabel           matlab.ui.control.Label
        ClearErrorButton         matlab.ui.control.Button
        ThingstoNoteButton       matlab.ui.control.StateButton
        ThingstoNotePanel        matlab.ui.container.Panel
        RapidChangePanel         matlab.ui.container.Panel
        RapidChangeLabel         matlab.ui.control.Label
        ExtraLabel               matlab.ui.control.Label
        PositionDependencePanel  matlab.ui.container.Panel
        PositionDependenceLabel  matlab.ui.control.Label
        SecondaryActionPanel     matlab.ui.container.Panel
        SecondaryActionLabel     matlab.ui.control.Label
        PainPanel                matlab.ui.container.Panel
        PainLabel                matlab.ui.control.Label
        PrimaryActionPanel       matlab.ui.container.Panel
        PrimaryActionLabel       matlab.ui.control.Label
        NoteEditField            matlab.ui.control.EditField
        NoteEditFieldLabel       matlab.ui.control.Label
        UITable                  matlab.ui.control.Table
        ResetTablePanel          matlab.ui.container.Panel
        TableNodesEditField      matlab.ui.control.NumericEditField
        NodesEditFieldLabel      matlab.ui.control.Label
        ResetButton              matlab.ui.control.Button
        RegulationOuterPanel     matlab.ui.container.Panel
        RegulationInnerPanel     matlab.ui.container.Panel
        RegEditField             matlab.ui.control.EditField
        RegButton                matlab.ui.control.Button
        RegCheckBox              matlab.ui.control.CheckBox
    end


    properties (Access = public)
        nnp=[]; 
        callingFigure=[]; % handle to calling figure
        ltYellow=[1,1,0.8];
%         styleYellow=uistyle('BackgroundColor',[1,1,.8]) % light yellow background
%         styleSelected=uistyle('BackgroundColor',[1,1,.8],'FontWeight','bold'); % light yellow background
        styleUpdated=uistyle('FontColor',[0,0,0],'FontWeight','bold'); % light yellow background
        repeatOn=false; % key repeated before release
        bigStep=10;
        littleStep=1;
%         stepSizeMult=3;
        stepSize=1; %step size when updating
        keyRelUpdate=false; % update on keyRelease if a second keyPresss doesn't occur
        lockoutSec=0.30; % delay to wait between updates when held (should be longer than nnp.timeout)
        newTime=0; %time since last update
        lockoutCounter=0; % when multiple keypresses are detected without key release, this increments
        lockoutCountLimit=10; % may want to set this to be the same as bigSteps, but for now make separate
        checkLockout=false; % look for lockout - reset on KeyRelease
%         lastStimUpdate=0;
%         stimUpdateLimit=0.25;  %250ms between updates
        lastUpdate=[];
        totalRows=24; % default to 6 nodes
        PAinc=5;
        saveFile=[];
%         TableUpdates=[];
        toastTimer=[];
        StimOnActiveColor = [0.4 1 0.4]% Description
%         StimOnInactiveColor = [0.35 .5 0.35]% Description
        StimOnInactiveColor = [0.95 .95 0.95]% Description
%         StimOffActiveColor = [1 0.2 0.2]% Description
%         StimOffInactiveColor = [.5 0.35 0.35]% Description
%         StimOffInactiveColor = [1 0.2 0.2]% Description
% Code for auto file open
         userFolders=struct;  % userfiles, code, user, config, data, log
         defaultSuffix='_Profile.mat';
         defaultImageSuffix='_SystemDiagram.png';
         defaultName='P00_Profile.mat';
         showNumpadImage=false;
    end

    properties (Access = private)
        stimOn = false; 
        lastrowselected=0; 
        altPressed = false;
        shiftPressed = false;
        ctrlPressed = false;
        clearPressed = false;
        insertPressed = false;
        
        updatingStim = false;
        UnselectedColor =[]; %stores row striping color 
%         colNum=struct('LastChecked', 1, 'Node', 2, 'Ch', 3, 'Muscle', 4,...
%                       'ThPW', 5, 'ThA', 6, 'MaxPW', 7, 'MaxA', 8, 'Flag', 9, 'Notes', 10);
          colNum=struct('LastChecked', 1, 'Node', 2, 'Ch', 3, 'Muscle', 4,...
                      'Amp', 5, 'ThPW', 6, 'MaxPW', 7, 'Type', 8, 'Flag', 9, 'Notes', 10);
          % ThPW from 5 to 6
          % Amp from 6 and 8 to only 5
          % Col 8 from MaxA to Type
          typeList={'WE';'WF';'FDS';'FDP';'INT';'FE';'TE';'TF';'TAD';'TABB';'TABL';'TRI';'OTH'};
    end

    methods (Access = private)
    
        function resetStim(app, varargin)
            if nargin == 2
                forceOff = varargin{1};
            else
                forceOff = false;
            end
                
            
            %if the PW and PA are both non zero, turn off Stim, before swithcing channels.  
            % Otherwise, leave everything as is.  If stim is turned off, highlight the non-zero
            % values so operator is reminded to set them appropriately before turning stim back on
            % use Force off between nodes
            if app.stimOn && (forceOff || (app.PWEditField.Value ~= 0 && app.PAEditField.Value ~= 0)) 
                StimOFFButtonPushed(app, 0);
                
                %Highlight the non-zero values
                if app.PWEditField.Value ~= 0
                    app.PWEditField.BackgroundColor = [1, 1, 0];
                end
                if app.PAEditField.Value ~= 0
                    app.PAEditField.BackgroundColor = [1, 1, 0];
                end
            end
        end
       
    
        function error = setStim(app)
            app.PWEditField.BackgroundColor = [1, 1, 1];
            app.PAEditField.BackgroundColor = [1, 1, 1];
            
            node = app.NodeEditField.Value;
            ch = app.ChannelEditField.Value;
            pw = app.PWEditField.Value;
            pa = app.PAEditField.Value;
            
            result = app.nnp.write(node, '3212', ch, uint8([pw pa]));
            if isequal(result, 0)
               error = 0;
               app.clearError();
            else
                error = 1;
                app.setError();
                %indicate that values may not have updated
                app.PWEditField.BackgroundColor = [1, 0, 0];
                app.PAEditField.BackgroundColor = [1, 0, 0];
                app.Toast(sprintf(['The Pulse Width/Amplitude may not have updated correctly\n'...
                                    'Error:' app.nnp.lastError]));
            end
            
            if app.RegCheckBox.Value
                result = app.nnp.write(node, '3210', 5, uint8([3 3 3 3]));
                if isequal(result, 0)
                    app.RegEditField.Value = 'Cleared Status. Press Update';
                else
                    warning('could not set reg status to 3')
                end
            end
        end
        
        function clearError(app)
            app.ErrorLamp.Color = [0.9 0.9 0.9];
            app.ErrorEditField.Value = '';
        end
        
        function setError(app)
            app.ErrorLamp.Color = [1 0 0];
            app.ErrorEditField.Value = app.nnp.lastError;
            %TODO:trigger light turning off after 1 s
        end


        function rowNum = getRowNum(app)
            node=app.NodeEditField.Value;
            chan=app.ChannelEditField.Value;
            rowNum=(node-1)*4+chan;
            if rowNum>size(app.UITable.Data, 1)
                rowNum =1;
            end
        end
        
        function nextRow(app)
            % instead of stimOff, I want to set the PW to 0
            % to do that I need to call app.ZeroPWButtonPushed()
            app.ZeroPWButtonPushed();
            pause(.2);
%             if app.stimOn == true 
%                 app.StimOFFButtonPushed();
%                 drawnow;
%             end

%             if app.stimOn == false % add a warning here to let us know
            if app.PWEditField.Value == 0 % add a warning here to let us know
                currentRow=getRowNum(app); %current row
                nextRow=currentRow+1;
                if nextRow<=app.totalRows % not at the bottom
                    %update Node and Channel only (updateRow uses these to update others)
                    row=nextRow;
                    data = table2cell(app.UITable.Data(row,:));
                    app.NodeEditField.Value = data{app.colNum.Node};
                    app.ChannelEditField.Value = data{app.colNum.Ch};

                    app.updateRow();
                end
            else
                msgbox('Could not stop stim before changing channels.  Channel not changed')

                lastRow=app.getRowNum(); % keep selection where it was
                app.UITable.Selection=[lastRow,1];
            end 

        end
        
        function prevRow(app)
            app.ZeroPWButtonPushed();
            pause(.2);

%             if app.stimOn == true 
%                 app.StimOFFButtonPushed();
%                 drawnow;
%             end
            if app.PWEditField.Value == 0 % add a warning here to let us know
%             if app.stimOn == false % add a warning here to let us know
                currentRow=getRowNum(app); %current row
                nextRow=currentRow-1;
                if nextRow>=1 % not less than 1
                    %update Node and Channel only (updateRow uses these to update others)
                    row=nextRow;
                    data = table2cell(app.UITable.Data(row,:));
                    app.NodeEditField.Value = data{app.colNum.Node};
                    app.ChannelEditField.Value = data{app.colNum.Ch};

                    app.updateRow();
                end
            else
                msgbox('Could not stop stim before changing channels.  Channel not changed')

                lastRow=app.getRowNum(); % keep selection where it was
                app.UITable.Selection=[lastRow,1];
            end 
        end
        
        function formatCells(app)
            row = app.lastrowselected;
            bg = app.UnselectedColor;
            bg(row,:) = [1 1 0]; %yellow
            app.UITable.BackgroundColor = bg;

%             removeStyle(app.UITable);
%             addStyle(app.UITable,app.styleSelected,'row',row);
% 
%             styleGray=uistyle('FontColor',[0.75,0.75,0.75]);
%             [row,col] = find(app.TableUpdates==0);
%             if ~isempty(row)
%                 addStyle(app.UITable,styleGray,'cell',[row,col]);
%             end
        end
        
        function HideHelp(app, obj, event)
            app.HelpPanel.Visible = false;
        end
        
        function Toast(app, message)
            if ~isempty(app.toastTimer) && isequal(app.toastTimer.Running, 'on')
                stop(app.toastTimer);
            end
            app.HelpLabel.Text = message;
%             app.HelpPanel.Visible = true;
            app.HelpLabel.Visible = true;
%             app.toastTimer.StartDelay = 10; %time message stays visible
%             start(app.toastTimer);
        end
        
        function OpenFileByName(app,filename,filepath)
           if filename
                app.saveFile=fullfile(filepath, filename); %save for default for later
                ld = load(fullfile(filepath, filename));
                %check for proper fields like we do in patternedit
                if ~isfield(ld,'data')
                    msgbox('Profiler Data file contains data field', 'History File', 'error')
                    return
                end
                data = ld.data;
                %Error checking on input file
                if size(data, 2) ~= 10
                    msgbox('Profiler Data file does not have 10 columns of data', 'History File', 'error')
                    return
                end
                if size(data,1) == 0
                    msgbox('Profiler Data file contains no rows', 'History File', 'error')
                    return
                end
                node = cell2mat(data(:,app.colNum.Node));
                if any(~ismember(node, 1:127))
                    msgbox('Column 3 (Node) must be 1-127', 'History File', 'error')
                    return
                end
                ch = cell2mat(data(:,app.colNum.Ch));
                if any(~ismember(ch, 1:4))
                    msgbox('Column 4 (Ch) must be 1-4', 'History File', 'error')
                    return
                end
               % 5 is Amp (for both)
                pw = cell2mat(data(:,app.colNum.ThPW));
                if any(~ismember(pw, 0:255))
                    msgbox('Column 6 (PW) must be 0-255', 'History File', 'error')
                    return
                end
                pa = cell2mat(data(:,app.colNum.Amp));
                if any(~ismember(pa, 0:200))
                    msgbox('Column 5 (PA) must be 0-200', 'History File', 'error')
                    return
                end
                pw = cell2mat(data(:,app.colNum.MaxPW));
                if any(~ismember(pw, 0:255))
                    msgbox('Column 7 (PW) must be 0-255', 'History File', 'error')
                    return
                end

                app.UITable.Data = cell2table(data);
                app.TableNodesEditField.Value= round(size(data,1)/4);
                app.totalRows = size(data,1);

            else
                if isempty(app.UITable.Data)
                    nodes = 3;
                    app.ResetUITable(nodes);
                    app.TableNodesEditField.Value = nodes;
                    app.totalRows=4*nodes;
                end
            end
            app.UITable.Selection=[1,1];                
            app.lastrowselected=1;
            updateRow(app);

            figure(app.UIFigure); %return focus after nnp opened if needed  
        end
    end
    
    methods (Access = public)
        
        function ResetUITable(app, nodes)
            totalrows=nodes*4;
            app.UITable.Selection=[];% deselect before resetting


            removeStyle(app.UITable); % here I am removing before adding later.  I could only add at startup and not on reset
             % I could make selection style always the Nth style,
             % depending upon number of rows
            %app.UITable.Data = % start with fresh table and create
            data = cell(totalrows,10); 
%             app.TableUpdates=zeros(size(data));
%             app.TableUpdates(:,2:4) = 1;

            for i=1:nodes
                for j=1:4
                    thisrow=(i-1)*4+j;
                    node = i;
                    ch = j;
                    musc=sprintf('N%dC%d',node,ch);
                    flag=false;
                    mtype='OTH';
                    amp = 100;
                    thpw = 0;
                    maxpw = 0;
                                        
                    %data = app.UITable.Data;      
 
                    %colNum=struct('LastChecked', 1, 'Node', 2, 'Ch', 3, 'Muscle', 4,...
                    %  'Amp', 5, 'ThPW', 6, 'MaxPW', 7, 'Type', 8, 'Flag', 9, 'Notes', 10);
                    data(thisrow,:) =  [{[], node, ch, musc, amp, thpw, maxpw, mtype, flag,  ''}];
                    
                end
            end
            app.UITable.Selection=[1,1];
            app.UITable.Data = cell2table(data);
            app.NodeEditField.Value = 1;
            app.ChannelEditField.Value = 1;
            
            app.PAEditField.Value = 100;
            app.PWEditField.Value = 0;
            app.FlagButton.Value = false;
            row1=table2cell(app.UITable.Data(1,:));
            app.MuscleEditField.Value=row1{app.colNum.Muscle};% muscle in row 1
            val=row1{app.colNum.Type}; 
            tindex = find(strcmp(app.typeList, val));
                    if isempty(tindex)
                            app.TypeDropDown.Value=num2str(13);% Other
                            % could give error here
                    else
                        app.TypeDropDown.Value=num2str(tindex);
                    end
            app.lastrowselected=1;
            app.formatCells();
        end
        
        function updateRow(app)
            % Set the selection to the correct row based on the current
            % values of node and channel
                row=app.getRowNum();  % need to first set these with cell selection function

            % run the cell selection function
                data = table2cell(app.UITable.Data(row,:));


                app.NodeEditField.Value = data{app.colNum.Node};
                app.ChannelEditField.Value = data{app.colNum.Ch};
                app.PAEditField.Value = data{app.colNum.Amp}; %max amp level;
%                 app.PWEditField.Value = 0; % reset to 0
                
                % add Muscle and notes
                app.MaximumPullButton.Text = num2str(data{app.colNum.MaxPW});
                app.ThresholdPullButton.Text = num2str(data{app.colNum.ThPW});
                app.MuscleEditField.Value=data{app.colNum.Muscle};
                val=data{1,app.colNum.Type}; 
                tindex = find(strcmp(app.typeList, val));
                    if isempty(tindex)
                            app.TypeDropDown.Value=num2str(13);% Other
                            % could give error here
                    else
                        app.TypeDropDown.Value=num2str(tindex);
                    end
                app.FlagButton.Value=data{app.colNum.Flag};
                if app.FlagButton.Value
                    app.FlagButton.BackgroundColor = [1 .5 0];
                    app.FlagButton.FontColor = [0 0 0];
                else
                    app.FlagButton.BackgroundColor = [.96 .96 .96];
                    app.FlagButton.FontColor = [.7 .7 .7];
                end
                app.NoteEditField.Value=data{app.colNum.Notes};
                app.lastrowselected=row;

                app.formatCells();
        end
        
        function results = overrideNumLock(app,key)
            % Override the numlock by converting the numpad keys to the
            % non-lock values
            switch key
                case 'numpad0'
                    results='insert';
                case 'numpad1'
                    results='end';
                case 'numpad2'
                    results='downarrow';
                case 'numpad3'
                    results='pagedown';
                case 'numpad4'
                    results='leftarrow';
                case 'numpad5'
                    results='clear';
                case 'numpad6'
                    results='rightarrow';
                case 'numpad7'
                    results='home';
                case 'numpad8'
                    results='uparrow';
                case 'numpad9'
                    results='pageup';
                case 'decimal'
                    results='delete';
                otherwise
                    results=key;
            end
            
        end
        
        function processUpDownKey(app,key)  %from testKeyRepeat
%             %if app.clearPressed || app.ctrlPressed
%              if app.insertPressed || app.ctrlPressed
%                 ss=app.stepSize*app.stepSizeMult;
%             else
%                 ss=app.stepSize;
%             end
           
            switch key
                case 'uparrow'
                    unit=app.stepSize; % increasing
                case 'downarrow'
                    unit=-app.stepSize; % decreasing
            end

            value = app.PWEditField.Value + unit;
            if value > 255
                value = 255;
            elseif value < 0
                value = 0;
            end
            app.PWEditField.Value = value;
            
            %apply
            app.setStim();
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin<2
                NNP=[];
            else
                NNP=varargin{1};
            end
            useNNP=true;
            if isempty(NNP) || ~isvalid(NNP) %if being called after nnp cancelled from another app
                app.nnp = NNPAPI();
                if isempty(app.nnp.port)
                    % msgbox('No port chosen for NNP Access Point')
                    uialert(app.UIFigure,'No port chosen for NNP Access Point','No NNP AP')
                    delete(app.nnp)
                    useNNP=false;
%                     return
                end
            else
                app.nnp = NNP;
            end
            if nargin>2
                app.callingFigure=varargin{2};
            end
            if nargin>3  % third is userFolders
                app.userFolders=varargin{3};  % userfiles, code, user, config, data, log
                app.UserIDEditField.Value=app.userFolders.code;
                % next need to load the profile
            end

            stripe = [ 1.0000    1.0000    1.0000
                0.9400    0.9400    0.9400];
            app.UITable.BackgroundColor = repmat(stripe, 24, 1); %support up to 48 rows in table
            app.UnselectedColor = app.UITable.BackgroundColor;
            if useNNP
                StimOFFButtonPushed(app, 0);
            end
            app.ThingstoNotePanel.Visible = false;
            app.SystemImagePanel.Visible = false;

%             app.ResetUITable(3);
%            app.OpenMenuSelected();
%             app.RemoteTunerCheckBox.Visible = false;  %Invisible for now because not implemented yet
%             app.ActionButtonGroup.Visible = false;
            RegCheckBoxValueChanged(app, 0);
            app.lastUpdate=tic;%initialize tic
%             app.lastStimUpdate=tic; % initialize tic

%             app.toastTimer = timer;
%             app.toastTimer.TimerFcn = @app.HideHelp;
%             app.Toast(sprintf(['For each channel, use the buttons or remote keypad to adjust the Pulse Width to find:'...
%                                   '\nThreshold: lowest value at which the stimulation is noticeable'...
%                                   '\nMaximum: highest level before stimulation is uncomfortable or spills'...
%                                   '\n                 over to other actions' ]));
            
            app.StimONButton.BackgroundColor = app.StimOnInactiveColor;
%             app.StimOFFButton.BackgroundColor = app.StimOffActiveColor;
            drawnow;
%             for i=2:6
%                 pause(2);
%                 app.HelpImage.ImageSource = ['help_' num2str(i) '.png'];
%             end 
%             pause(2);
%             app.HelpImage.Visible = false;
            if isfield(app.userFolders,'config')&& ~isempty(app.userFolders.config)
                if isfield(app.userFolders,'code')
                    filename=[app.userFolders.code,app.defaultSuffix];
                    app.defaultName=filename; % this is reduntant, could combine above
                    filepath=app.userFolders.config;
                    fullName=fullfile(filepath,filename);
                    if exist(fullName,'file')==2  % make sure file exists before trying to open
                        app.OpenFileByName(filename,filepath);
                    end
                    % insert user image to app.Image.ImageSource
                    filename=[app.userFolders.code,app.defaultImageSuffix];
                    filepath=app.userFolders.config;
                    fullName=fullfile(filepath,filename);
                    if exist(fullName,'file')==2  % make sure file exists before trying to open
                        app.Image.ImageSource=fullName;
                        %do I need to refresh the image?
                    end
                end
            end

        end

        % Button pushed function: PrevChButton
        function PrevChButtonPushed(app, event)
            figure(app.UIFigure);
            app.prevRow()
%             ch = app.ChannelEditField.Value;
%             if ch>1
%                 app.ChannelEditField.Value = ch-1;
%             end
%             app.resetStim();
%             app.updateRow();
        end

        % Button pushed function: NextChButton
        function NextChButtonPushed(app, event)
            figure(app.UIFigure);
            app.nextRow()
%             ch = app.ChannelEditField.Value;
%             if ch<4
%                 app.ChannelEditField.Value = ch+1;
%             end
%             app.resetStim();
%             app.updateRow();
        end

        % Callback function
        function PrevNodeButtonPushed(app, event)
            figure(app.UIFigure);
            node = app.NodeEditField.Value;
            if node>1
                app.NodeEditField.Value = node-1;
            end
            app.resetStim(true); %force stim off
            app.updateRow();
        end

        % Callback function
        function NextNodeButtonPushed(app, event)
           figure(app.UIFigure);
           node = app.NodeEditField.Value;
            if node<app.TableNodesEditField.Value
                app.NodeEditField.Value = node+1;
            end
            app.resetStim(true); %force stim off
            app.updateRow();
        end

        % Button pushed function: StimONButton
        function StimONButtonPushed(app, event)
            figure(app.UIFigure);
            %Stim ON button also turns stim off if stim is active
            if app.stimOn
                StimOFFButtonPushed(app, []);
                return;
            end

            retry = 0;
            nodeErr=-1;
            while(nodeErr)             
                error = app.setStim();
                if error
                    nodeErr = app.NodeEditField.Value;
                else
                    nodeErr = 0;
                end
                retry = retry+1;
                if retry == 3
                    [modes, ~, ~, net] = app.nnp.getStatus();
                    if net==0
                        resp = questdlg('Network not On.  Turn on network?', 'Profiler');
                        if isequal(resp, 'Yes')
                            if ~app.nnp.networkOn()
                                msgbox('Could not turn on Network', 'Profiler', 'error');
                            end
                        end
                    elseif net==1
                        if nodeErr>0
                            if ~isequal(modes(nodeErr), 'Waiting')
                                resp = questdlg(['Node(s) ' num2str(nodeErr') ' is not online and in waiting mode.  Cycle Network?'], 'Profiler');
                                if isequal(resp, 'Yes')
                                    if ~app.nnp.networkOff()
                                        msgbox('Could not turn off Network', 'Profiler', 'error');
                                    end
                                    if ~app.nnp.networkOn()
                                        msgbox('Could not turn on Network', 'Profiler', 'error');
                                    end
                                end
                            else
                                msgbox(['Node(s) ' num2str(nodeErr') ' is online and in waiting mode, but could not be set'], 'Profiler', 'error');
                            end
                        end
                    else
                         msgbox('Network status could not be determined', 'Profiler', 'error');
                    end
                    return;
                end
            end
            if app.nnp.enterTestStim
                app.stimOn = true;
                app.PWEditField.FontColor = [0 0 0];
                app.UIFigure.Color = app.StimOnActiveColor;
                row = app.getRowNum();
                datestr = char(datetime);
                app.UITable.Data(row,1) ={datestr(1:end-3)};
%                 app.RemoteTunerOuterPanel.BackgroundColor = [0.7,1,0.7];
%                 app.RegulationOuterPanel.BackgroundColor = [0.7,1,0.7];
%                 app.StimONButton.BackgroundColor = [0.7,1,0.7];
                app.StimONButton.BackgroundColor = app.StimOnActiveColor;
                app.StimONButton.FontColor = [0 0 0];
%                 app.StimOFFButton.BackgroundColor = app.StimOffInactiveColor;
%                 app.StimOFFButton.FontColor =  [0.7 0.7 0.7];
                addStyle(app.UITable, app.styleUpdated, 'cell', [row 1])
%                 app.TableUpdates(row,1) = 1;
%                 app.formatCells();
            else
                app.setError();
            end
        end

        % Button pushed function: StimOFFButton
        function StimOFFButtonPushed(app, event)
            figure(app.UIFigure);
            if app.nnp.enterWaiting
                app.stimOn = false;
                 app.PWEditField.FontColor = [0.8 0.8 0.8];
                app.UIFigure.Color = [0.94,0.94,0.94];
%                 app.RemoteTunerOuterPanel.BackgroundColor = [0.94,0.94,0.94];
%                 app.RegulationOuterPanel.BackgroundColor = [0.94,0.94,0.94];
                
                app.StimONButton.BackgroundColor = app.StimOnInactiveColor;
                app.StimONButton.FontColor = [0.7 0.7 0.7];
%                 app.StimOFFButton.BackgroundColor = app.StimOffActiveColor;
%                 app.StimOFFButton.FontColor = [0 0 0];
            else
                app.setError();
            end       
        end

        % Value changed function: PAEditField
        function PAEditFieldValueChanged(app, event)
%             value = app.PAEditField.Value*10)/10;
%             app.PAEditField.Value = value;

            %apply
            app.setStim();
        end

        % Value changed function: PWEditField
        function PWEditFieldValueChanged(app, event)
            %Edit field forces rounded value
%             value = app.PWEditField.Value;
            
            %apply
            app.setStim();
        end

        % Value changed function: NodeEditField
        function NodeEditFieldValueChanged(app, event)
            app.resetStim();
        end

        % Value changed function: ChannelEditField
        function ChannelEditFieldValueChanged(app, event)
            app.resetStim();
        end

        % Callback function
        function PAUpButtonPushed(app, event)
            inc = app.PAinc;
            value = app.PAEditField.Value + inc;
            if value > 200
                value = 200;
            end
            app.PAEditField.Value = value;

             %apply
            app.setStim();
        end

        % Callback function
        function PADnButtonPushed(app, event)
            inc = app.PAinc;
            value = app.PAEditField.Value - inc;
            if value < 0
                value = 0;
            end
            app.PAEditField.Value = value;

            %apply
            app.setStim();
        end

        % Button pushed function: PWDnButton
        function PWDnButtonPushed(app, event)
            figure(app.UIFigure);
            value = app.PWEditField.Value - app.littleStep;
            if value < 0
                value = 0;
            end
            app.PWEditField.Value = value;
            app.HelpPanel.Visible = false;
            %apply
            app.setStim();
        end

        % Button pushed function: PWUpButton
        function PWUpButtonPushed(app, event)
            figure(app.UIFigure);
            value = app.PWEditField.Value + app.littleStep;
            if value > 255
                value = 255;
            end
            if value ==255
                app.Toast('Pulse Width is maxed out');
            end
            app.PWEditField.Value = value;
            
            %apply
            app.setStim();
        end

        % Value changed function: RegCheckBox
        function RegCheckBoxValueChanged(app, event)
            app.RegulationInnerPanel.Visible=app.RegCheckBox.Value;
            figure(app.UIFigure);
        end

        % Button pushed function: RegButton
        function RegButtonPushed(app, event)

            if app.RegCheckBox.Value
                node = app.NodeEditField.Value;
                ch = app.ChannelEditField.Value;
                
                result = app.nnp.read(node, '3210', 5, 'uint8');
                if length(result) == 4
                    switch result(ch)
                        case 0, app.RegEditField.Value = '0: Out of Regulation!';
                        case 1, app.RegEditField.Value = '1: In Regulation';
                        case 2, app.RegEditField.Value = '2: Pulse<10us';
                        case 3, app.RegEditField.Value = '3: No pulse yet';
                        otherwise, app.RegEditField.Value = [num2str(result(ch)) ': ?'];  
                    end
                else
                    app.RegEditField.Value = 'Could not read';  
                end
                
            end

        end

        % Button pushed function: ZeroPWButton
        function ZeroPWButtonPushed(app, event)
            
            app.PWEditField.Value=0;
             %apply
            app.setStim();
            figure(app.UIFigure);
        end

        % Button pushed function: ClearErrorButton
        function ClearErrorButtonPushed(app, event)
            % clear the error message and change LED back to grey
            app.ErrorLamp.Color=[0.90,0.90,0.90];
            app.ErrorEditField.Value='';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ishandle(app.callingFigure) % check that is is valid
                figure(app.callingFigure); % bring to front
            end
            delete(app)
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
          
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
            % I need to add a default path, but need to check if path is
            % valid first:
            if isfield(app.userFolders,'config')&& ~isempty(app.userFolders.config)
                    fname=[app.userFolders.code,app.defaultSuffix];
                    defname=fullfile(app.userFolders.config,fname); % name with path                  
            else
                defname=app.defaultName; % name without path
            end
            [filename, filepath] = uiputfile('.mat','Save Profiler User Data',defname);
            if filename
                app.saveFile=[filepath filename];
                data = table2cell(app.UITable.Data);
                save([filepath filename], 'data');
            end
            figure(app.UIFigure);
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            [filename, filepath] = uigetfile('.mat','Select Profiler History file');
            app.OpenFileByName(filename,filepath);
%{
             % to remove block temporarily
                if filename
                    app.saveFile=[filepath filename]; %save for default for later
                    ld = load([filepath filename]);
                    data = ld.data;
                    %Error checking on input file
                    if size(data, 2) ~= 10
                        msgbox('Profiler Data file does not have 10 columns of data', 'History File', 'error')
                        return
                    end
                    if size(data,1) == 0
                        msgbox('Profiler Data file contains no rows', 'History File', 'error')
                        return
                    end

                    node = cell2mat(data(:,app.colNum.Node));
                    if any(~ismember(node, 1:127))
                        msgbox('Column 3 (Node) must be 1-127', 'History File', 'error')
                        return
                    end
                    ch = cell2mat(data(:,app.colNum.Ch));
                    if any(~ismember(ch, 1:4))
                        msgbox('Column 4 (Ch) must be 1-4', 'History File', 'error')
                        return
                    end
                    % 5 is Amp (for both)
                    pw = cell2mat(data(:,app.colNum.ThPW));
                    if any(~ismember(pw, 0:255))
                        msgbox('Column 6 (PW) must be 0-255', 'History File', 'error')
                        return
                    end
                    pa = cell2mat(data(:,app.colNum.Amp));
                    if any(~ismember(pa, 0:200))
                        msgbox('Column 5 (PA) must be 0-200', 'History File', 'error')
                        return
                    end
                    pw = cell2mat(data(:,app.colNum.MaxPW));
                    if any(~ismember(pw, 0:255))
                        msgbox('Column 7 (PW) must be 0-255', 'History File', 'error')
                        return
                    end

                    app.UITable.Data = cell2table(data);
                    app.TableNodesEditField.Value= round(size(data,1)/4);
                    app.totalRows = size(data,1);

                else
                    if isempty(app.UITable.Data)
                        nodes = 3;
                        app.ResetUITable(nodes);
                        app.TableNodesEditField.Value = nodes;
                        app.totalRows=4*nodes;
                    end
                end
                app.UITable.Selection=[1,1];
                app.lastrowselected=1;
                updateRow(app);
                figure(app.UIFigure); %return focus after nnp opened if needed
            %}
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            figure(app.UIFigure);
            row = event.Indices(1);
            col = event.Indices(2);
            
            val = str2double(event.EditData);
            switch col
                case app.colNum.Node 
                    if ~ismember(val, 1:127)
                        app.UITable.Data(row, col) = {event.PreviousData};
                    else
                        app.UITable.Data(row, col) = {val};
                    end
                case app.colNum.Ch
                    if ~ismember(val, 1:4)
                        app.UITable.Data(row, col)  = {event.PreviousData};
                    else
                        app.UITable.Data(row, col) = {val};
                    end
                case app.colNum.ThPW 
                    if ~ismember(val, 0:255)
                        app.UITable.Data(row, col)  = {event.PreviousData};
                    else
                        app.UITable.Data(row, col) = {val};
                    end
                case app.colNum.Amp
                    if ~ismember(val, 0:200)
                        app.UITable.Data(row, col)  = {event.PreviousData};
                    else
                        app.UITable.Data(row, col) = {val};
                    end
                case app.colNum.MaxPW 
                    if ~ismember(val, 0:255)
                        app.UITable.Data(row, col)  = {event.PreviousData};
                    else
                        app.UITable.Data(row, col) = {val};
                    end
                case app.colNum.Type
                    index = find(strcmp(app.typeList, val));
                    if isempty(index)
                            app.UITable.Data(row, col)  = {event.PreviousData};
                            % could give error here
                    else
                        app.UITable.Data(row, col) = {val};
                    end
%                 case app.colNum.Amp 
%                     if ~ismember(val, 0:200)
%                         app.UITable.Data(row, col)  = {event.PreviousData};
%                     else
%                         app.UITable.Data(row, col) = {val};
%                     end
%                 case 10 %pw
%                     if ~ismember(val, 0:255)
%                         app.UITable.Data(row, col)  = {event.PreviousData};
%                     else
%                         app.UITable.Data(row, col) = {val};
%                     end
%                 case 11 %pa
%                     if ~ismember(val, 0:200)
%                         app.UITable.Data(row, col)  = {event.PreviousData};
%                     else
%                         app.UITable.Data(row, col) = {val};
%                     end
            end      
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            figure(app.UIFigure);
            if isempty(event.Indices)
                return;
            end
            row = event.Indices(1);
            col = event.Indices(2);             
%             app.lastrowselected=row; % need to revert back to this if stim On

            % the code below needs to be put into a separate function so
            % that I can call it when changing row programmatically
            % Below I only change these numbers if stim is off, but that
            % would throw off the row pointer.  
            % What I need to do is first stop the stim (as if hitting
            % stimOff, then run the code below.
            % Will need to decide what to do if an error occurs
            % Alternatively, I could not allow selection of row if stim is
            % ON.  That would mean returning to prior selection and giving
            % a warning.
%             if col == 1 && app.stimOn == false

            if app.stimOn == true
                app.StimOFFButtonPushed([]);
            end

            if app.stimOn == false
                data = table2cell(app.UITable.Data(row,:));
                app.NodeEditField.Value = data{app.colNum.Node };
                app.ChannelEditField.Value = data{app.colNum.Ch };
               app.updateRow();
            else
                msgbox('Could not stop stim before changing channels.  Channel not changed')

                lastRow=app.getRowNum(); % keep selection where it was
                app.UITable.Selection=[lastRow,col];
            end 
%             figure(app.UIFigure);
        end

        % Button pushed function: ThreshButton
        function ThreshButtonPushed(app, event)
          %fill in reference cells in row
            row=app.getRowNum(); % should also be able to use lastrowselected
            pw = app.PWEditField.Value;
            pa = app.PAEditField.Value;
            data = table2cell(app.UITable.Data(row,:));
            data{app.colNum.ThPW }=pw;
            data{app.colNum.Amp }=pa;
            app.UITable.Data(row,:)=data;  
            addStyle(app.UITable, app.styleUpdated, 'cell', [row app.colNum.ThPW; row app.colNum.Amp])
            figure(app.UIFigure);

%             if app.UITable.Data{row, app.colNum.ThA}~=app.UITable.Data{row, app.colNum.MaxA}
%                  app.Toast('The Threshold and Maximum Pulse Widths were not recorded at same Amplitude.');
%             end
            app.updateRow();
        end

        % Button pushed function: MaxButton
        function MaxButtonPushed(app, event)
             %fill in reference cells in row
            row=app.getRowNum(); % should also be able to use lastrowselected
            pw = app.PWEditField.Value;
            pa = app.PAEditField.Value;
            data = table2cell(app.UITable.Data(row,:));
            data{app.colNum.MaxPW}=pw;
            data{app.colNum.Amp}=pa;
            app.UITable.Data(row,:)=data;
            addStyle(app.UITable, app.styleUpdated, 'cell', [row app.colNum.MaxPW; row app.colNum.Amp])
%             app.TableUpdates(row,7:8)=1;
%             app.formatCells();
            str = [];
            if pw==255 && pa<200
                str = sprintf('The Pulse Width is maxed out.\nConsider increasing Amplitude to get more strength.');
            elseif pw<app.UITable.Data{row, app.colNum.ThPW}
                str = sprintf('The Maximum Pulse Width is lower than the Threshold Pulse Width!');
            elseif pw<100 
                str = sprintf('The Maximum Pulse Width is fairly low.\nConsider decreasing Amplitude to get more dynamic range.');
            end

%             if app.UITable.Data{row, app.colNum.Amp}~=app.UITable.Data{row, app.colNum.Amp}
%                  if ~isempty(str)
%                      str = [str sprintf('\n\n')];
%                  end
%                  str = [str 'The Threshold and Maximum Pulse Widths were not recorded at same Amplitude.'];
%             end
            if ~isempty(str)
                app.Toast(str);
            end
            app.updateRow();


            figure(app.UIFigure);
        end

        % Value changed function: MuscleEditField
        function MuscleEditFieldValueChanged(app, event)
            value = app.MuscleEditField.Value;
            row=app.getRowNum(); % should also be able to use lastrowselected
            %change table to match value
            data = app.UITable.Data(row,:);            
            data{app.colNum.Muscle}=value;
            app.UITable.Data(row,:)=data;
            figure(app.UIFigure);
        end

        % Value changed function: NoteEditField
        function NoteEditFieldValueChanged(app, event)
            value = app.NoteEditField.Value;
            row=app.lastrowselected;
%             row=app.getRowNum(); % should also be able to use lastrowselected
            %change table to match value
            data = app.UITable.Data(row,:);            
            data{app.colNum.Notes}=value;
            app.UITable.Data(row,:)=data;
            addStyle(app.UITable, app.styleUpdated, 'cell', [row app.colNum.Notes])

            figure(app.UIFigure);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if app.OverrideNumlockCheckBox.Value
                key= app.overrideNumLock(key);
            end
            switch key
                case 'alt'
                    app.altPressed = true;
                case 'shift'
                    app.shiftPressed = true;
                case 'control'
                    app.ctrlPressed = true;
                
                case 'insert'
                    app.insertPressed = true;
                case {'semicolon'} % decrease amp
                    
            
                case 'quote' % increase amp
   

                case {'period','pageup'} % may need to see how the rows advance (up or down)
                    app.prevRow();
%                     if app.altPressed % increase amp
%                         PAUpButtonPushed(app, 0);
%                     else
%                         NextNodeButtonPushed(app, 0);
%                     end

            
                case {'comma','pagedown'}
                    app.nextRow();
%                     if app.altPressed % decrease amp
%                          PADnButtonPushed(app, 0);
%                     else
%                         PrevNodeButtonPushed(app, 0);
%                     end            


%                 case 'uparrow'
%                         PWUpButtonPushed(app,0);
%                 case 'downarrow'
%                         PWDnButtonPushed(app,0);
                case {'uparrow', 'downarrow'} %up or down arrow
%                     app.lockoutSec=0.10; % added here to change without restarting
                    
                    if app.checkLockout   % after key release, do not need to check lockout
                        app.newTime=toc(app.lastUpdate);
                        if app.newTime>app.lockoutSec % ignore if not long enough
                            app.lastUpdate=tic;
                            app.stepSize=app.bigStep; % held so us big step
                            app.processUpDownKey(key);
                            app.checkLockout=true;
                            
                            app.keyRelUpdate=false;
                        end
                    else % not checkLockout
                    
                        app.checkLockout=true;

                        if app.keyRelUpdate % should always be false at this point
                           
                            app.keyRelUpdate=false; % more than one KP
                            % Since this is first duplicate, send out change
                            app.lastUpdate=tic;
                            app.stepSize=app.bigStep; % held so us big step
                            app.processUpDownKey(key);
                        
                        else % not keyRelease and not checkLockout
                            app.keyRelUpdate=true; % first time through
                        end
                    end
            end
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            key = event.Key;
            if app.OverrideNumlockCheckBox.Value
                key= app.overrideNumLock(key);
            end
            app.KeyEditField.Value=key;
            app.respEditField.Value='';

            switch key
%                 case 'leftarrow' % channel down  
%                     app.respEditField.Value='left';         
%                     % PrevChButtonPushed(app,0);
%                     app.prevRow();
%                 case 'rightarrow' % channel up   
%                     app.respEditField.Value='right';
%                     app.nextRow();
%                     % NextChButtonPushed(app,0);
                
                case 'alt'
                    app.altPressed = false;
                     app.respEditField.Value='alt';
%                case {'clear', 'numpad5'}
%                     app.HelpPanel.Visible = ~app.HelpPanel.Visible;

                case 'shift'
                    app.shiftPressed = false;
                     app.respEditField.Value='shift';
            
                case 'control'
                    app.ctrlPressed = false;
                    app.respEditField.Value='ctl';
                case {'backslash','multiply'} % copy PW/Amp to REF in table
                    % app.clearPressed = false;
%                     RefButtonPushed(app, 0);
                case {'home','semicolon'}
                    % Max PW/Amp in profile
                        MaxButtonPushed(app,0);
                case {'end','quote'}
                    % Threshold PW/Amp in profile
                        ThreshButtonPushed(app,0);
                case 'rightbracket' % Current row not active
                    % Unmute all
                    app.respEditField.Value='rb';

                case 'leftbracket' % current row active
                    % Mute all
                    app.respEditField.Value='lbt';
                case 'insert'
                    app.insertPressed = false;
                    app.respEditField.Value='ins';
                case 'divide'
                    if app.insertPressed % for normal keypad, numpad not locked
                        % current active
                        app.respEditField.Value='divide';
                    else
                        % current not active
            
                    end
              

                    % update row
            
                case {'uparrow', 'downarrow'} %up or down arrow
                    if app.keyRelUpdate
                        app.lastUpdate=tic;
                        app.stepSize=app.littleStep;
                        app.processUpDownKey(key);
                    end
            
                case {'hyphen','delete'}
                    StimOFFButtonPushed(app, 0);
            
                case {'subtract','equal', 'clear'} % stim toggle
                    if app.stimOn
                        StimOFFButtonPushed(app, 0);
                    else
                        StimONButtonPushed(app, 0);
                    end
            
                case 'space'
                    app.respEditField.Value='space';
                    if app.ctrlPressed && app.stimOn == false
                        StimONButtonPushed(app, 0);
                    else
                        StimOFFButtonPushed(app, 0);
                    end
            
            end
            %for key repeat logic
            app.checkLockout=false;
            app.keyRelUpdate=false;

        end

        % Value changed function: TableNodesEditField
        function TableNodesEditFieldValueChanged(app, event)
%             value = app.TableNodesEditField.Value;
%             newRows=value*4;
%             if newRows<app.totalRows
%                 refillrows=newRows;
%                 % give warning that you are going to lose data
%                 % ask for response
%                 % if no, then return
%                 % otherwise copy as much as you can
%             else
%                 refillrows=app.totalRows; % still the old value
%             end
%             data=app.UITable.Data; %make a copy of prior data
%             app.ResetUITable();
%             newData=app.UITable.Data;
%             for i=1:refillrows
%                 newData(i,:)=data(i,:);
%             end
%             app.UITable.Data=newData;    
%             app.totalRows=4*value; % add this here in case I change the default number of nodes
        end

        % Value changed function: ThingstoNoteButton
        function ThingstoNoteButtonValueChanged(app, event)
            app.ThingstoNotePanel.Visible = app.ThingstoNoteButton.Value;
            figure(app.UIFigure);
        end

        % Menu selected function: XMLMenu
        function XMLMenuSelected(app, event)
            % save the user profile data as XML file
            % get a file name and save the data to that file
            % include a default file name.  
            % start with the current filename, with xml extension for
            % default
            [~,a,~]=fileparts(app.saveFile); % ADD THUIS LATER
            %a=[]; %placeholder for save file
            if isempty(a)
                defaultName='PxProfileData.xml';
            else
                defaultName = [a,'.xml'];
            end
            [filename, pathname ] = uiputfile('*.xml', 'Save Profile Data as XML',defaultName);
            if filename == 0
                figure(app.UIFigure); %return focus after nnp opened if needed
                return
            else
                xmlSaveFile = fullfile(pathname, filename); % do not save this file name
                data=table2cell(app.UITable.Data(:,2:end)); % omit the select column because cannot write empty cells to XML
                variableNames={'Node'; 'Ch'; 'Muscle'; 'Amp'; 'ThPW'; 'MaxPW'; 'Type'; 'Notes'};
                profileTable=cell2table(data,'VariableNames',variableNames); % converts cell array to table
                writetable(profileTable,xmlSaveFile,'FileType','xml','TableNodeName','profileTable');
                %                 app.TopMessage('Profile Data saved to file');
            end
            figure(app.UIFigure); %return focus after nnp opened if needed
        end

        % Value changed function: SystemImageButton
        function SystemImageButtonValueChanged(app, event)
            app.SystemImagePanel.Visible = app.SystemImageButton.Value;
            figure(app.UIFigure);
        end

        % Button pushed function: PWUpBigButton
        function PWUpBigButtonPushed(app, event)
            figure(app.UIFigure);
            value = app.PWEditField.Value + app.bigStep;
            if value > 255
                value = 255;
            end
            if value ==255
                app.Toast('Pulse Width is maxed out');
            end
            app.PWEditField.Value = value;
            
            %apply
            app.setStim();
        end

        % Button pushed function: PWDnBigButton
        function PWDnBigButtonPushed(app, event)
            figure(app.UIFigure);
            value = app.PWEditField.Value - app.bigStep;
            if value < 0
                value = 0;
            end
            app.PWEditField.Value = value;
            app.HelpPanel.Visible = false;
            %apply
            app.setStim();
        end

        % Button pushed function: ThresholdPullButton
        function ThresholdPullButtonPushed(app, event)
            figure(app.UIFigure);
            row=app.getRowNum(); % should also be able to use lastrowselected
            %change table to match value
            app.PWEditField.Value = app.UITable.Data{row,app.colNum.ThPW};   
            app.PAEditField.Value = app.UITable.Data{row,app.colNum.Amp};   
            %apply
            app.setStim();
        end

        % Button pushed function: MaximumPullButton
        function MaximumPullButtonPushed(app, event)
            figure(app.UIFigure);
            row=app.getRowNum(); % should also be able to use lastrowselected
            %change table to match value
            app.PWEditField.Value = app.UITable.Data{row,app.colNum.MaxPW};   
            app.PAEditField.Value = app.UITable.Data{row,app.colNum.Amp};   
            %apply
            app.setStim();
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            if isempty(app.saveFile) 
                app.SaveAsMenuSelected(event);
            else
                data = table2cell(app.UITable.Data);
                save(app.saveFile, 'data');
            end
            figure(app.UIFigure);
        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
            resp = inputdlg('Enter the number of stimulus modules (PG4) for the Profiler Table: (1-6)', 'New Profiler Table');
            if ~isempty(resp) 
                nodes = str2double(resp);
                if ~isnan(nodes) && nodes>0 && nodes<=6
                    app.ResetUITable(nodes);
                    app.TableNodesEditField.Value = nodes;
                    app.totalRows=4*nodes; 
                end
            end
            figure(app.UIFigure);
        end

        % Callback function
        function PullfromTableButtonPushed(app, event)
            app.PullfromTablePanel.Visible = ~app.PullfromTablePanel.Visible;
        end

        % Button down function: HelpPanel
        function HelpPanelButtonDown(app, event)
            app.HelpPanel.Visible = false;
            figure(app.UIFigure);
        end

        % Image clicked function: HelpOverlayImage
        function HelpOverlayImageClicked(app, event)
            app.HelpPanel.Visible = false;
            app.HelpMessagesButton.Value = false;
            figure(app.UIFigure);
        end

        % Button down function: PositionDependencePanel, 
        % PrimaryActionPanel, RapidChangePanel, SecondaryActionPanel, 
        % ThingstoNotePanel
        function ThingstoNotePanelButtonDown(app, event)
            app.ThingstoNotePanel.Visible = false;
            app.ThingstoNoteButton.Value = false;
            figure(app.UIFigure);
        end

        % Callback function: Image, SystemImagePanel
        function SystemImagePanelButtonDown(app, event)
            app.SystemImagePanel.Visible = false;
            app.SystemImageButton.Value = false;
            figure(app.UIFigure);
        end

        % Value changed function: HelpMessagesButton
        function HelpMessagesButtonValueChanged(app, event)
%             app.HelpPanel.Visible = app.HelpMessagesButton.Value;
%             figure(app.UIFigure);
        end

        % Value changed function: FlagButton
        function FlagButtonValueChanged(app, event)
            value = app.FlagButton.Value;
            if value
                app.FlagButton.BackgroundColor = [1 .5 0];
                app.FlagButton.FontColor = [0 0 0];
            else
                app.FlagButton.BackgroundColor = [.96 .96 .96];
                app.FlagButton.FontColor = [.7 .7 .7];
            end
            row=app.getRowNum(); % should also be able to use lastrowselected
            %change table to match value
            data = table2cell(app.UITable.Data(row,:));            
            data{app.colNum.Flag}=value;
            app.UITable.Data(row,:)=data;
            addStyle(app.UITable, app.styleUpdated, 'cell', [row app.colNum.Flag])

            figure(app.UIFigure);
        end

        % Button pushed function: AmpDownButton
        function AmpDownButtonPushed(app, event)
            pa = app.PAEditField.Value;
            pa = pa - 10;
            if pa < 0
                pa = 0;
            end
            app.PAEditField.Value = pa;
            app.PAEditFieldValueChanged([]);
        end

        % Button pushed function: AmpUpButton
        function AmpUpButtonPushed(app, event)
            pa = app.PAEditField.Value;
            pa = pa + 10;
            if pa > 200
                pa = 200;
            end
            app.PAEditField.Value = pa;
            app.PAEditFieldValueChanged([]);
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            value = app.TypeDropDown.Value; % this will be string index
            type=app.typeList(str2double(value)); % this will be string
            % when this changes, I need to update the current row, col=
            % colNum.Type
            row=app.getRowNum();
            data = app.UITable.Data(row,:);            
            data{1,app.colNum.Type}=type;
            app.UITable.Data(row,:)=data;
            figure(app.UIFigure);
        end

        % Menu selected function: fromSubwayDiagramMenu
        function fromSubwayDiagramMenuSelected(app, event)
           % Import data for the profile from the subway diagram file to use as starting point for profile.
             % load the subway data from a file
            [filename, filepath] = uigetfile('.mat', 'Select Node and Electrode Placement File'); % subway Table file
            if filename
                ld = load([filepath filename]);
                TempFile = [filepath filename];  % save filename as temporary until the file is checked
            else
                return;
            end
            fields = {'subwayTable','subwayTitle','diagramTitle'};
            iMissing = find(~isfield(ld, fields));

            str = [];
            for i=1:length(iMissing)
                str = [str ', ' fields{iMissing(i)}];
            end
            if ~isempty(str)
                warndlg(['Invalid subway Parameter File.  Does not contain variable(s): ' str '.'], 'Open File')
                return;
            end
            subwayTable=ld.subwayTable;
            Istim=strcmp(subwayTable{:,'dType'},"Stim");
            stimTable=subwayTable(Istim,[3,19:22]); % I only need node and labels
            stimTable=sortrows(stimTable,1); %sort by node
            nodeNum=size(stimTable,1);
            nodeChanRows=nodeNum*4;
            nodeChanArray=cell(nodeChanRows,3);

            for i=1:nodeNum
                for j=1:4
                    row=(i-1)*4+j;
                    nodeChanArray{row,1}=stimTable{i,1};%node
                    nodeChanArray{row,2}=j;%ch
                    nodeChanArray{row,3}=stimTable{i,j+1}; %label
                end
            end
            newData=cell(nodeChanRows,10);
            %colNum=struct('LastChecked', 1, 'Node', 2, 'Ch', 3, 'Muscle', 4, 'Amp', 5, 'ThPW', 6, 'MaxPW', 7, 'Type', 8, 'Flag', 9, 'Notes', 10);
            newData(:,[2,3,4])=nodeChanArray(:,[1,2,3]);

            % add default data
            defaultRow={200,0,0,'OTH',false,''};
            for i=1:nodeChanRows
                newData(i,[5:10])=defaultRow(1:6);
            end
            app.UITable.Data=cell2table(newData);   
            figure(app.UIFigure);
        end

        % Menu selected function: NumPadHelpMenu
        function NumPadHelpMenuSelected(app, event)
            %toggle numpad help on or off
            app.showNumpadImage=~app.showNumpadImage;
            app.NumPadHelpPanel.Visible=app.showNumpadImage;
        end

        % Image clicked function: numpadimage
        function numpadimageImageClicked(app, event)
          app.showNumpadImage=~app.showNumpadImage;
          app.NumPadHelpPanel.Visible=app.showNumpadImage;   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Stimulation Profiler';
            app.UIFigure.Icon = 'NNP.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Text = 'SaveAs';
            app.ExportMenu = uimenu(app.UIFigure);
            app.ExportMenu.Text = 'Export';
            app.XMLMenu = uimenu(app.ExportMenu);
            app.XMLMenu.MenuSelectedFcn = createCallbackFcn(app, @XMLMenuSelected, true);
            app.XMLMenu.Text = 'XML';
            app.ImportMenu = uimenu(app.UIFigure);
            app.ImportMenu.Text = 'Import';
            app.fromSubwayDiagramMenu = uimenu(app.ImportMenu);
            app.fromSubwayDiagramMenu.MenuSelectedFcn = createCallbackFcn(app, @fromSubwayDiagramMenuSelected, true);
            app.fromSubwayDiagramMenu.Text = 'from Subway Diagram';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.NumPadHelpMenu = uimenu(app.HelpMenu);
            app.NumPadHelpMenu.MenuSelectedFcn = createCallbackFcn(app, @NumPadHelpMenuSelected, true);
            app.NumPadHelpMenu.Text = 'NumPad Help';
            app.RegulationOuterPanel = uipanel(app.UIFigure);
            app.RegCheckBox = uicheckbox(app.RegulationOuterPanel);
            app.RegCheckBox.ValueChangedFcn = createCallbackFcn(app, @RegCheckBoxValueChanged, true);
            app.RegCheckBox.Tooltip = {'Check compliance of stim channels'};
            app.RegCheckBox.Text = 'Regulation ';
            app.RegulationInnerPanel = uipanel(app.RegulationOuterPanel);
            app.RegulationInnerPanel.Visible = 'off';
            app.RegButton = uibutton(app.RegulationInnerPanel, 'push');
            app.RegButton.ButtonPushedFcn = createCallbackFcn(app, @RegButtonPushed, true);
            app.RegButton.Tooltip = {'Reg of current channel (all active channels if history table Reg checkbox selected'};
            app.RegButton.Text = {'Update'; 'Status'};
            app.RegEditField = uieditfield(app.RegulationInnerPanel, 'text');
            app.ResetTablePanel = uipanel(app.UIFigure);
            app.ResetTablePanel.Title = 'Reset Table';
            app.ResetButton = uibutton(app.ResetTablePanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.NodesEditFieldLabel = uilabel(app.ResetTablePanel);
            app.NodesEditFieldLabel.Text = 'Nodes';
            app.TableNodesEditField = uieditfield(app.ResetTablePanel, 'numeric');
            app.TableNodesEditField.Limits = [1 6];
            app.TableNodesEditField.ValueChangedFcn = createCallbackFcn(app, @TableNodesEditFieldValueChanged, true);
            app.TableNodesEditField.Value = 6;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Last Checked'; 'Node'; 'Ch'; 'Muscle'; 'Amp'; 'ThPW'; 'MaxPW'; 'Type'; '!'; 'Notes'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true false false false false false false false false false];
            app.UITable.ColumnEditable = [false true true true true true true true true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.Multiselect = 'off';
            app.NoteEditFieldLabel = uilabel(app.UIFigure);
            app.NoteEditFieldLabel.Text = 'Note';
            app.NoteEditField = uieditfield(app.UIFigure, 'text');
            app.NoteEditField.ValueChangedFcn = createCallbackFcn(app, @NoteEditFieldValueChanged, true);
            app.ThingstoNotePanel = uipanel(app.UIFigure);
            app.ThingstoNotePanel.Title = 'Things to Note';
            app.ThingstoNotePanel.ButtonDownFcn = createCallbackFcn(app, @ThingstoNotePanelButtonDown, true);
            app.PrimaryActionPanel = uipanel(app.ThingstoNotePanel);
            app.PrimaryActionPanel.Title = 'Primary Action';
            app.PrimaryActionPanel.ButtonDownFcn = createCallbackFcn(app, @ThingstoNotePanelButtonDown, true);
            app.PrimaryActionLabel = uilabel(app.PrimaryActionPanel);
            app.PrimaryActionLabel.Text = {'What does it mainly do?  Even if it'; 'spills to another action or was not '; 'intended for this action.'};
            app.PainPanel = uipanel(app.ThingstoNotePanel);
            app.PainPanel.Title = 'Pain';
            app.PainLabel = uilabel(app.PainPanel);
            app.PainLabel.Text = {'Anything uncomfortable. Note whether '; 'it is due to position, stimulus level '; 'or the rate the stimulation is applied'};
            app.SecondaryActionPanel = uipanel(app.ThingstoNotePanel);
            app.SecondaryActionPanel.Title = 'Secondary Action';
            app.SecondaryActionPanel.ButtonDownFcn = createCallbackFcn(app, @ThingstoNotePanelButtonDown, true);
            app.SecondaryActionLabel = uilabel(app.SecondaryActionPanel);
            app.SecondaryActionLabel.Text = {'If it spills to another muscle, it is helpful '; 'to know where, how strongly, '; 'and whether it is a helpful addition for '; 'a particular pattern.'};
            app.PositionDependencePanel = uipanel(app.ThingstoNotePanel);
            app.PositionDependencePanel.Title = 'Position Dependence';
            app.PositionDependencePanel.ButtonDownFcn = createCallbackFcn(app, @ThingstoNotePanelButtonDown, true);
            app.PositionDependenceLabel = uilabel(app.PositionDependencePanel);
            app.PositionDependenceLabel.Text = {'Note changes due to arm, hand or wrist '; 'position.  Note if consistent or irregular. '; 'Note how it changes with stim level.'};
            app.ExtraLabel = uilabel(app.ThingstoNotePanel);
            app.ExtraLabel.Text = {'Note anything unusual or significant to monitor or help '; 'in pattern editing'};
            app.RapidChangePanel = uipanel(app.ThingstoNotePanel);
            app.RapidChangePanel.Title = 'Rapid Change';
            app.RapidChangePanel.ButtonDownFcn = createCallbackFcn(app, @ThingstoNotePanelButtonDown, true);
            app.RapidChangeLabel = uilabel(app.RapidChangePanel);
            app.RapidChangeLabel.Text = {'Also called ''high gain'', meaning there is an'; 'area that changes rapidly with PW. Note where,'; ' and adjust levels in the pattern editor'};
            app.ThingstoNoteButton = uibutton(app.UIFigure, 'state');
            app.ThingstoNoteButton.ValueChangedFcn = createCallbackFcn(app, @ThingstoNoteButtonValueChanged, true);
            app.ThingstoNoteButton.Text = 'Things to Note';
            app.DebugPanel = uipanel(app.UIFigure);
            app.DebugPanel.Title = 'Debug Panel';
            app.ClearErrorButton = uibutton(app.DebugPanel, 'push');
            app.ClearErrorButton.ButtonPushedFcn = createCallbackFcn(app, @ClearErrorButtonPushed, true);
            app.ClearErrorButton.Text = 'CLR';
            app.ErrorLampLabel = uilabel(app.DebugPanel);
            app.ErrorLampLabel.Text = 'Error';
            app.ErrorLamp = uilamp(app.DebugPanel);
            app.ErrorEditField = uieditfield(app.DebugPanel, 'text');
            app.ErrorEditField.Editable = 'off';
            app.KeyEditFieldLabel = uilabel(app.DebugPanel);
            app.KeyEditFieldLabel.Text = 'Key';
            app.KeyEditField = uieditfield(app.DebugPanel, 'text');
            app.respEditFieldLabel = uilabel(app.DebugPanel);
            app.respEditFieldLabel.Text = 'resp';
            app.respEditField = uieditfield(app.DebugPanel, 'text');
            app.OverrideNumlockCheckBox = uicheckbox(app.DebugPanel);
            app.OverrideNumlockCheckBox.Text = 'Override Numlock';
            app.OverrideNumlockCheckBox.Value = true;
            app.SystemImagePanel = uipanel(app.UIFigure);
            app.SystemImagePanel.Title = 'System Image';
            app.SystemImagePanel.ButtonDownFcn = createCallbackFcn(app, @SystemImagePanelButtonDown, true);
            app.Image = uiimage(app.SystemImagePanel);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @SystemImagePanelButtonDown, true);
            app.Image.ImageSource = 'Standard Hand Only NNP System Configuration.png';
            app.Panel = uipanel(app.UIFigure);
            app.ZeroPWButton = uibutton(app.Panel, 'push');
            app.ZeroPWButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroPWButtonPushed, true);
            app.ZeroPWButton.Text = 'Reset';
            app.SavetoTablePanel = uipanel(app.Panel);
            app.SavetoTablePanel.Title = 'Save to Table ►';
            app.MaxButton = uibutton(app.SavetoTablePanel, 'push');
            app.MaxButton.ButtonPushedFcn = createCallbackFcn(app, @MaxButtonPushed, true);
            app.MaxButton.Icon = 'max.png';
            app.MaxButton.Text = '';
            app.ThreshButton = uibutton(app.SavetoTablePanel, 'push');
            app.ThreshButton.ButtonPushedFcn = createCallbackFcn(app, @ThreshButtonPushed, true);
            app.ThreshButton.Icon = 'Thresh.png';
            app.ThreshButton.Text = '';
            app.PAEditField = uieditfield(app.Panel, 'numeric');
            app.PAEditField.Limits = [0 200];
            app.PAEditField.ValueDisplayFormat = '%.0f';
            app.PAEditField.ValueChangedFcn = createCallbackFcn(app, @PAEditFieldValueChanged, true);
            app.PAEditField.Tooltip = {'Pulse Amplitude (in 0.1mA).  Set to a lower number if small Pulse Width changes result in large changes in output.  Set to a higher number if more strength is required at the maximum pulse width.'};
            app.PAEditField.Value = 100;
            app.AmplitudeLabel = uilabel(app.Panel);
            app.AmplitudeLabel.Text = 'Amplitude';
            app.PWDnBigButton = uibutton(app.Panel, 'push');
            app.PWDnBigButton.ButtonPushedFcn = createCallbackFcn(app, @PWDnBigButtonPushed, true);
            app.PWDnBigButton.Icon = 'pwdown_big.png';
            app.PWDnBigButton.Text = '';
            app.PWUpBigButton = uibutton(app.Panel, 'push');
            app.PWUpBigButton.ButtonPushedFcn = createCallbackFcn(app, @PWUpBigButtonPushed, true);
            app.PWUpBigButton.Icon = 'pwup_big.png';
            app.PWUpBigButton.Text = '';
            app.ChannelEditFieldLabel = uilabel(app.Panel);
            app.ChannelEditFieldLabel.Text = 'Channel';
            app.ChannelEditField = uieditfield(app.Panel, 'numeric');
            app.ChannelEditField.Limits = [1 4];
            app.ChannelEditField.RoundFractionalValues = 'on';
            app.ChannelEditField.ValueDisplayFormat = '%.0f';
            app.ChannelEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelEditFieldValueChanged, true);
            app.ChannelEditField.Editable = 'off';
            app.ChannelEditField.Value = 1;
            app.NodeEditFieldLabel = uilabel(app.Panel);
            app.NodeEditFieldLabel.Text = 'Node';
            app.NodeEditField = uieditfield(app.Panel, 'numeric');
            app.NodeEditField.Limits = [1 127];
            app.NodeEditField.RoundFractionalValues = 'on';
            app.NodeEditField.ValueDisplayFormat = '%.0f';
            app.NodeEditField.ValueChangedFcn = createCallbackFcn(app, @NodeEditFieldValueChanged, true);
            app.NodeEditField.Editable = 'off';
            app.NodeEditField.Value = 1;
            app.PWEditField = uieditfield(app.Panel, 'numeric');
            app.PWEditField.Limits = [0 255];
            app.PWEditField.RoundFractionalValues = 'on';
            app.PWEditField.ValueDisplayFormat = '%.0f';
            app.PWEditField.ValueChangedFcn = createCallbackFcn(app, @PWEditFieldValueChanged, true);
            app.PWDnButton = uibutton(app.Panel, 'push');
            app.PWDnButton.ButtonPushedFcn = createCallbackFcn(app, @PWDnButtonPushed, true);
            app.PWDnButton.Icon = 'pwdown.png';
            app.PWDnButton.Text = '';
            app.PWUpButton = uibutton(app.Panel, 'push');
            app.PWUpButton.ButtonPushedFcn = createCallbackFcn(app, @PWUpButtonPushed, true);
            app.PWUpButton.Icon = 'pwup.png';
            app.PWUpButton.Text = '';
            app.PWusSliderLabel = uilabel(app.Panel);
            app.PWusSliderLabel.Text = 'Pulse Width';
            app.StimONButton = uibutton(app.Panel, 'push');
            app.StimONButton.ButtonPushedFcn = createCallbackFcn(app, @StimONButtonPushed, true);
            app.StimONButton.Text = 'STIM';
            app.StimOFFButton = uibutton(app.Panel, 'push');
            app.StimOFFButton.ButtonPushedFcn = createCallbackFcn(app, @StimOFFButtonPushed, true);
            app.StimOFFButton.Text = {'STOP'; ''};
            app.PrevChButton = uibutton(app.Panel, 'push');
            app.PrevChButton.ButtonPushedFcn = createCallbackFcn(app, @PrevChButtonPushed, true);
            app.PrevChButton.Icon = 'icon_12.png';
            app.PrevChButton.Text = '';
            app.NextChButton = uibutton(app.Panel, 'push');
            app.NextChButton.ButtonPushedFcn = createCallbackFcn(app, @NextChButtonPushed, true);
            app.NextChButton.Icon = 'icon_13.png';
            app.NextChButton.Text = '';
            app.MuscleEditField = uieditfield(app.Panel, 'text');
            app.MuscleEditField.ValueChangedFcn = createCallbackFcn(app, @MuscleEditFieldValueChanged, true);
            app.MuscleEditField.Tooltip = {'Edit to change name in table'};
            app.MuscleEditField.Value = 'Muscle';
            app.PullfromTablePanel = uipanel(app.Panel);
            app.PullfromTablePanel.Title = 'Pull from Table ◄';
            app.MaximumPullButton = uibutton(app.PullfromTablePanel, 'push');
            app.MaximumPullButton.ButtonPushedFcn = createCallbackFcn(app, @MaximumPullButtonPushed, true);
            app.MaximumPullButton.Text = '0';
            app.ThresholdPullButton = uibutton(app.PullfromTablePanel, 'push');
            app.ThresholdPullButton.ButtonPushedFcn = createCallbackFcn(app, @ThresholdPullButtonPushed, true);
            app.ThresholdPullButton.Text = '0';
            app.AmpUpButton = uibutton(app.Panel, 'push');
            app.AmpUpButton.ButtonPushedFcn = createCallbackFcn(app, @AmpUpButtonPushed, true);
            app.AmpUpButton.Text = '▲';
            app.AmpDownButton = uibutton(app.Panel, 'push');
            app.AmpDownButton.ButtonPushedFcn = createCallbackFcn(app, @AmpDownButtonPushed, true);
            app.AmpDownButton.Text = '▼';
            app.TypeDropDown = uidropdown(app.Panel);
            app.TypeDropDown.Items = {'WE - wrist extensor', 'WF - wrist flexor', 'FDS - finger flexor superficial (PIP)', 'FDP - finger flexor deep (DIP)', 'INT - intrinsics', 'FE - finger extensor', 'TE - thumb extensor', 'TF - thumb flexor', 'TAD - thumb adductor', 'TABB - thumb abductor brevis (intrinsic)', 'TABL - thumb adbuctor longus (extrinsic)', 'TRI - triceps', 'OTH - other'};
            app.TypeDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = '13';
            app.UserIDEditField = uieditfield(app.Panel, 'text');
            app.UserIDEditField.Editable = 'off';
            app.SystemImageButton = uibutton(app.UIFigure, 'state');
            app.SystemImageButton.ValueChangedFcn = createCallbackFcn(app, @SystemImageButtonValueChanged, true);
            app.SystemImageButton.Icon = 'SystemImageButton.PNG';
            app.SystemImageButton.Text = '';
            app.HelpPanel = uipanel(app.UIFigure);
            app.HelpPanel.ButtonDownFcn = createCallbackFcn(app, @HelpPanelButtonDown, true);
            app.HelpImage = uiimage(app.HelpPanel);
            app.HelpImage.Visible = 'off';
            app.HelpImage.ImageSource = 'help_1.png';
            app.HelpLabel = uilabel(app.HelpPanel);
            app.HelpLabel.Text = {'Hello'; ''};
            app.HelpOverlayImage = uiimage(app.HelpPanel);
            app.HelpOverlayImage.ImageClickedFcn = createCallbackFcn(app, @HelpOverlayImageClicked, true);
            app.HelpOverlayImage.ImageSource = 'warnPanel.png';
            app.HelpMessagesButton = uibutton(app.UIFigure, 'state');
            app.HelpMessagesButton.ValueChangedFcn = createCallbackFcn(app, @HelpMessagesButtonValueChanged, true);
            app.HelpMessagesButton.Text = 'Help Messages';
            app.FlagButton = uibutton(app.UIFigure, 'state');
            app.FlagButton.ValueChangedFcn = createCallbackFcn(app, @FlagButtonValueChanged, true);
            app.FlagButton.Text = '!';
            app.NumPadHelpPanel = uipanel(app.UIFigure);
            app.NumPadHelpPanel.Title = 'NumPadHelp';
            app.NumPadHelpPanel.Visible = 'off';
            app.numpadimage = uiimage(app.NumPadHelpPanel);
            app.numpadimage.ImageClickedFcn = createCallbackFcn(app, @numpadimageImageClicked, true);
            app.numpadimage.ImageSource = 'numpadProfiler.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = chanProfiler(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end