classdef emgRecordRawCue < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        LogEMGPanel                  matlab.ui.container.Panel
        UserIDEditField              matlab.ui.control.EditField
        ShowLastButton               matlab.ui.control.Button
        NewLogFile                   matlab.ui.control.Button
        FolderEditField              matlab.ui.control.EditField
        FolderButton                 matlab.ui.control.Button
        LogFilenameEditField         matlab.ui.control.EditField
        LogEMGSwitch                 matlab.ui.control.Switch
        LogEMGLabel                  matlab.ui.control.Label
        FullLogNameEditField         matlab.ui.control.EditField
        VnetEditField                matlab.ui.control.NumericEditField
        VnetEditFieldLabel           matlab.ui.control.Label
        StimPanel                    matlab.ui.container.Panel
        SetVOSminCheckBox            matlab.ui.control.CheckBox
        StopNetButton                matlab.ui.control.Button
        ErrorEditField               matlab.ui.control.EditField
        ErrorLabel                   matlab.ui.control.Label
        ErrorLamp                    matlab.ui.control.Lamp
        ErrorLampLabel               matlab.ui.control.Label
        VOSEditField                 matlab.ui.control.NumericEditField
        VOSEditFieldLabel            matlab.ui.control.Label
        LoadfromFileButton           matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        IncludeStimLabel             matlab.ui.control.Label
        IncludeStimButton            matlab.ui.control.StateButton
        PAEditField                  matlab.ui.control.NumericEditField
        AmpLabel                     matlab.ui.control.Label
        PWEditField                  matlab.ui.control.NumericEditField
        PWLabel                      matlab.ui.control.Label
        ChanEditField                matlab.ui.control.NumericEditField
        ChannelLabel                 matlab.ui.control.Label
        NodeEditField                matlab.ui.control.NumericEditField
        NodeLabel                    matlab.ui.control.Label
        PanelEMG                     matlab.ui.container.Panel
        TabGroup                     matlab.ui.container.TabGroup
        CuesTab                      matlab.ui.container.Tab
        UseButton                    matlab.ui.control.StateButton
        CueLabel                     matlab.ui.control.Label
        SetTab                       matlab.ui.container.Tab
        CueTimesSecondsLabel         matlab.ui.control.Label
        RelaxEditField               matlab.ui.control.NumericEditField
        RelaxEditFieldLabel          matlab.ui.control.Label
        ContractEditField            matlab.ui.control.NumericEditField
        ContractEditFieldLabel       matlab.ui.control.Label
        DelayEditField               matlab.ui.control.NumericEditField
        DelayEditFieldLabel          matlab.ui.control.Label
        ViewEMGButton                matlab.ui.control.StateButton
        GainDropDown                 matlab.ui.control.DropDown
        ChannelDropDown              matlab.ui.control.DropDown
        EMGAxes                      matlab.ui.control.UIAxes
        BPAppRevisionEditField       matlab.ui.control.NumericEditField
        BPAppRevisionEditFieldLabel  matlab.ui.control.Label
        BPPCBSerialEditField         matlab.ui.control.NumericEditField
        BPPCBSerialEditFieldLabel    matlab.ui.control.Label
        NodeDropDown                 matlab.ui.control.DropDown
        NodeDropDownLabel            matlab.ui.control.Label
        PMAppRevisionEditField       matlab.ui.control.NumericEditField
        PMAppRevisionLabel           matlab.ui.control.Label
        PMPCBSerialEditField         matlab.ui.control.NumericEditField
        PMPCBSerialEditFieldLabel    matlab.ui.control.Label
    end

    
    properties (Access = public)
        nnp = [];
        node = [];
        
        updateTimer = [];
        emgbuf = [];
        emgTimer = [];
        emgSamp =[];
        doUpdate = false;
        pauseTime = 0.01;
        logfileid=[];
        plotcycle=4; %plot every forth cycle, makeing this 5Hz update
        cycle=0;
        FullLogName=[];
        CueParameters=[31,62,42]; % startdelay, relax, contract cycles at 48ms each
        callingFigure=[]; %handle to calling figure
        % Code for auto userID open
         userFolders=struct;  % userfiles, code, user, config, data, log, where code is file prefix
          defaultSuffix='_'; %before all the date/time information is added
         defaultName='P00_'; %if userFolders is not valid use this
         defaultPath=pwd;
    end
    
    properties (Access = private)
        savedSettings = [];
        savedTimeout =[];
        savedPMworInt=[];
        selpath=pwd;% get the current working directory
        LogFileName='TempLogFileName.bin'; % Save this to a temporary file
        LFileID=double([]); % File ID for Log File
        snode=1; % start with single channel.  Add vector later
        schan=1; % start with single channel.  Add vector later
        lastrowselected=0;
        stimOn = false;
        Cue=0; % Contraction cue, 0=none, 1=relax, 2=contract
        CueArray=zeros(2,48); % easiest to decode later if saved in columns
    end
    
    methods (Access = public)
        
        function updateEMG(app, obj, event)
            resp = app.nnp.read(7, '2053', 3, 'uint8');
            app.emgbuf(1:end-48) = app.emgbuf(49:end); % the 49th byte os a radio packet counter
            if length(resp) == 49
                app.emgbuf(end-48+1:end) = resp(1:48)';
            else
                app.emgbuf(end-48+1:end) = nan(48,1);
            end
            app.EMGAxes.Children.YData = app.emgbuf;
            if app.UseButton.Value
                app.getCueLevel();
            end
        end
        
        %---------------------------------------------------------------------------------
        function updateEMGfile(app, obj, event)
            % can use TasksExecuted to set the cueing.
            resp = app.nnp.read(7, '2053', 3, 'uint8');
            app.emgbuf(1:end-48) = app.emgbuf(49:end);
            if length(resp) == 49
                newdata = resp(1:48)';
            else
                newdata = nan(48,1); %
            end
            app.emgbuf(end-48+1:end) = newdata;
            if app.UseButton.Value
                app.getCueLevel();
            end
            % If I update every cycle, then I am updating the plot at 20
            % Hz.  Could probably update slower, like 10 or 5 hz.  To do
            % that I could have a running counter to determine when to
            % update.
            if app.cycle>=app.plotcycle
                app.EMGAxes.Children.YData = app.emgbuf; %update plot
                app.cycle=0;
            else
                app.cycle=app.cycle+1; %increment cycle and skip update
            end
            if app.UseButton.Value
                % if using cues, then I need to save the cue as separate array
                % Cues should always be 1 or 2 if used
                newdata2d=[newdata';app.CueArray(app.Cue,:)];
                fwrite(app.LFileID,newdata2d,'single'); % log newdata to single to preserve nan and inf
            else
                fwrite(app.LFileID,newdata,'single'); % log newdata to single to preserve nan and inf
            end
        end
        %---------------------------------------------------------------------------------
        function NewLogFileName(app)
            switch app.ChannelDropDown.Value
                case 'Channel 1'
                    ModeChanstring='EMG1';
                case 'Channel 2'
                    ModeChanstring='EMG2';
                case 'Offset 1'
                    ModeChanstring='Off1';
                case 'Offset 2'
                    ModeChanstring='Off2';
                otherwise
                    error('Oth')
            end
            gainstring=sprintf('_G%s',app.GainDropDown.Value);
            userstring=app.defaultName;
            timestring=sprintf('_%02.0f',datevec(now));
            nodestring=sprintf('_N%d',int16(app.NodeEditField.Value));
            chanstring=sprintf('_C%d',int16(app.ChanEditField.Value));
            pwstring=sprintf('_pw%d',int16(app.PWEditField.Value));
            pastring=sprintf('_pa%d',int16(app.PAEditField.Value));
            cuestring='cue_';
            %
            if app.IncludeStimButton.Value==true
                app.LogFilenameEditField.Value=[userstring,cuestring,ModeChanstring,gainstring,timestring,nodestring,chanstring,pwstring,pastring,'.bin'];
            else
                app.LogFilenameEditField.Value=[userstring,cuestring,ModeChanstring,gainstring,timestring,'.bin'];
            end
            %
            app.FullLogName=fullfile(app.FolderEditField.Value,app.LogFilenameEditField.Value);
            app.FullLogNameEditField.Value=app.FullLogName;
            figure(app.UIFigure); %restore focus to main figure
        end
        %---------------------------------------------------------------------------------
        function  NewLogFolderName(app)
            newpath = uigetdir(app.defaultPath,'Folder to store Raw EMG files');
            if ischar(newpath) % if canceled, this is a number 0
                if isfolder(newpath)  % can only use this with character array
                    app.defaultPath=newpath;  % update the path if a new one given
                    app.FolderEditField.Value=app.defaultPath;
                    app.FolderButton.BackgroundColor=[0.96,0.96,0.96]; % from [1,.9,.6]
                end
            end
            figure(app.UIFigure); % Restore focus to this window
            %
        end
        
        function getCueLevel(app)
            % assume CueParameters are stored at app level
            % can use TasksExecuted to set the cueing.
            % ted=mod((a-d),cr)
            te=app.emgTimer.TasksExecuted;
            sd=app.CueParameters(1); % StartDelay cycles at 48ms
            rCycles=app.CueParameters(2); % 3 seconds ~= 62 cycles cycles
            cCycles=app.CueParameters(3); % 2 seconds ~= 42 cycles cycles
            cr=rCycles+cCycles;
            % want to countinue the cuing cycle as long as recording
            lastCue=app.Cue;
            if te>= sd  % are we past a single cuing set?
                ted=mod(te-sd,cr);
                if ted < cCycles  % contraction range
                    app.Cue=2;
                else
                    app.Cue=1; % relax range
                end
            else
                app.Cue=1;
            end
            if app.Cue~=lastCue
                app.CueChange(app.Cue);
            end
        end
        
        function CueChange(app,cue)
            switch cue
                case 0
                    app.CueLabel.Text=''; % always start with Relax when button pushed
                    app.CueLabel.BackgroundColor=[0.94,0.94,0.94]; % relax color
                    app.EMGAxes.BackgroundColor=[0.94,0.94,0.94]; % relax color
                case 1
                    app.CueLabel.Text='Relax'; % always start with Relax when button pushed
                    app.CueLabel.BackgroundColor=[0.78,0.97,0.96]; % relax color
                    app.EMGAxes.BackgroundColor=[0.94,0.94,0.94]; % relax color
                case 2
                    app.CueLabel.Text='Contract'; % always start with Relax when button pushed
                    app.CueLabel.BackgroundColor=[.5,1,.5]; % contract color
                    app.EMGAxes.BackgroundColor=[.9,1,.9]; % contract color
            end
        end
    end
    
    methods (Access = private)
        
        function restoreRadio(app)
            app.nnp.setRadioSettings(app.savedSettings);
            app.nnp.timeout = app.savedTimeout;
            if app.savedPMworInt > 0
                app.nnp.worOn(app.savedPMworInt); %reenable WOR on PM if it was enabled
            end
        end
        %---------------------------------------------------------------------------------
        function saveRadio(app)
            %read back AP settings
            settings = app.nnp.getRadioSettings();
            if isempty(settings)
                msgbox('could not read back AccessPoint Settings','Radio Configuration', 'error');
                return;
            else
                app.savedSettings = settings;
            end
            app.savedTimeout = app.nnp.timeout;
            
            %is PM WOR enabled?
            resp = double(app.nnp.read(7, '2600', 9, 'uint16'));
            if length(resp)==1
                app.savedPMworInt = resp;
            else
                msgbox('could not determine if PM has WOR enabled, will get set to default 20ms');
                app.savedPMworInt = 20;
            end
        end
        %---------------------------------------------------------------------------------
        function startEMG(app)
            app.enableAll('off');
            app.saveRadio();
            % need to check whether the network is on first.
            
            %for max bandwidth, we need to eliminate radio retries, shorten timeouts, and turn of WOR
            app.nnp.setRadio('timeout', 10, 'retries', 0');
            app.nnp.worOff();
            app.setNetworkV();
            app.setNetworkOn();
            pause(.2); % allow time for all nodes to start up
            % If I need to set VOSmax, here is where I would do that
            if app.SetVOSminCheckBox.Value
                VOSminpar=app.VOSEditField.Value*60;
                if app.nnp.write(app.snode,'3210',3,VOSminpar,'uint16')~=0
                    msgbox(['enter set VOS ', num2str(app.snode), ' may have failed'])
                    app.VOSEditField.BackgroundColor=[1,0,0];
                else
                    app.VOSEditField.BackgroundColor=[1,1,1];
                end
            end
            if app.IncludeStimButton.Value==true
                app.startStim();
            end
            app.nnp.timeout = 0.04;
            
            app.EMGAxes.Color = [1 1 1];
            
            %Set diagnostic Mode and channel
            app.ChannelDropDown.Enable = 'off';
            app.GainDropDown.Enable = 'off';
            switch app.ChannelDropDown.Value
                case 'Channel 1'
                    ch=1;
                    mode=0;
                case 'Channel 2'
                    ch=2;
                    mode = 0;
                case 'Offset 1'
                    ch=1;
                    mode=2;
                case 'Offset 2'
                    ch=2;
                    mode = 2;
                otherwise
                    error('unknown dropdown value')
            end
            
            if app.nnp.write(app.node, '3000', 1, mode, 'uint8')~=0
                msgbox(['enter diagnostic mode ', num2str(mode), ' may have failed'])
            end
            
            if ~app.nnp.enterTestRaw(app.node, ch)
                msgbox('Could not enter Raw MES mode')
            end
            start(app.emgTimer); %not log data to file
        end
        %---------------------------------------------------------------------------------
        function stopEMG(app)
            app.enableAll('on');
            stop(app.emgTimer);
            app.Cue=0;
            app.CueChange(app.Cue);
            app.ChannelDropDown.Enable = 'on';
            app.GainDropDown.Enable = 'on';
            app.restoreRadio();
            if ~app.nnp.enterWaiting()
                msgbox('Could not enter waiting mode')
            end
        end
        
        %---------------------------------------------------------------------------------
        function enableAll(app, enable)
            app.ViewEMGButton.Enable = enable;
            app.NodeDropDown.Enable = enable;
        end
        %---------------------------------------------------------------------------------
        
        function  startStim(app)
            app.snode=app.NodeEditField.Value;
            app.schan=app.ChanEditField.Value;
            pw=app.PWEditField.Value;
            pa=app.PAEditField.Value*10;
            %             app.StimPanel.BackgroundColor=[.7,1,.5]; %change to see if it gets here
            app.StimPanel.BackgroundColor=[.7,1,.7];
            app.stimOn = true;
            result = app.nnp.write(app.snode, '3212', app.schan, uint8([pw pa])); % result for error check
        end
        
        %---------------------------------------------------------------------------------
        function stopStim(app)
            % When stimulating and recording EMG, it is more likely for the
            % remove modules to miss the enterwaiting command.  Therefore I am
            % going to turn off the network when stim stops to ensure they stop
            if ~app.nnp.enterWaiting()  %needs to be in waiting before network can be turned off, resp=1 is success
                msgbox('Could not enter waiting');
            end
            pause(.1)
            if ~app.nnp.networkOff()
                msgbox('Could not turn network off');
            end
            while (isempty(app.nnp.getNetworkStatus())||app.nnp.getNetworkStatus()==1) %but this calls the function twice
                resp = questdlg('Could not confirm network status, Retry?');
                if isequal(resp, 'No')
                    return;
                end
                if ~app.nnp.enterWaiting()  %needs to be in waiting before network can be turned off, resp=1 is success
                    msgbox('Could not enter waiting');
                end
                pause(.1)
                if ~app.nnp.networkOff()
                    msgbox('Could not turn network off');
                end
            end
            app.stimOn=false;
            app.StimPanel.BackgroundColor=[.94,.94,.94];
            % The result never comes back 0  Need to figure out what it should
            % be.
            %            result = app.nnp.write(app.snode, '3212', app.schan, uint8([0 0])); %result will be [] if error
            %              app.StimPanel.BackgroundColor=[.4,.8,.8];% shade different to see if it gets here
            %             if ~isempty(result) % response is not empty
            %                 error = 0;
            %                 app.stimOn = false;
            %                 app.StimPanel.BackgroundColor=[.94,.94,.94];
            %                 app.clearError();
            %                app.PWEditField.BackgroundColor = [1, 1, 1];
            %                app.PAEditField.BackgroundColor = [1, 1, 1];
            %             else
            %
            %                 app.StimPanel.BackgroundColor=[.6,.2,.94];
            %                 result = app.nnp.write(app.snode, '3212', app.schan, uint8([0 0])); %set to zeros a second time
            %
            %                 if ~isempty(result) % response is not empty
            %                     error = 0;
            %                     app.stimOn = false;
            % %                     app.StimPanel.BackgroundColor=[.94,.94,.94];
            %                     app.clearError();
            %
            %                 else
            %                     app.setError();  %need to add this function
            %                     %indicate that values may not have updated
            %                     error = 1;
            %                     app.PWEditField.BackgroundColor = [1, 0, 0];
            %                     app.PAEditField.BackgroundColor = [1, 0, 0];
            %                    app.StimPanel.BackgroundColor=[.94,.94,.94];
            %                 end
            %             end
        end
        %---------------------------------------------------------------------------------
        function clearError(app)
            app.ErrorLamp.Color = [0.9 0.9 0.9];
            app.ErrorEditField.Value = '';
            app.PWEditField.BackgroundColor = [1, 1, 1];
            app.PAEditField.BackgroundColor = [1, 1, 1];
            app.ErrorEditField.Value ='';
        end
        %---------------------------------------------------------------------------------
        function setError(app)
            app.ErrorLamp.Color = [1 0 0];
            app.ErrorEditField.Value = app.nnp.lastError;
            %TODO:trigger light turning off after 1 s
        end
        %---------------------------------------------------------------------------------
        function setNetworkV(app)
            if ~app.nnp.setVNET(app.VnetEditField.Value)  % this sets the network to 7.5v  I should not have this always set
                msgbox('could not set PM VNET');
                return;
            end
        end
        %---------------------------------------------------------------------------------
        
        function setNetworkOn(app)
            if ~app.nnp.enterWaiting()
                msgbox('could not enter waiting on PM');
                return;
            end
            
            if ~app.nnp.networkOn()
                msgbox('could not turn on network');
                return;
            end
            
            pause(0.2)
            while app.nnp.getNetworkStatus()~=1
                resp = questdlg('Could not confirm network status, Retry?');
                if isequal(resp, 'No')
                    return;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin<2
                NNP=[];
            else
                NNP=varargin{1};
            end
            useNNP=true; % allow figure to open without nnp
            if isempty(NNP)  || ~isvalid(NNP) %if being called after nnp cancelled from another app
                app.nnp = NNPAPI();
                if isempty(app.nnp.port)
%                     msgbox('No port chosen for NNP Access Point')
                    uialert(app.UIFigure,'No port chosen for NNP Access Point','No NNP AP')
                    useNNP=false;
                    delete(app.nnp)
%                    return
                end
            else
                app.nnp = NNP;
            end
            if nargin>2
                app.callingFigure=varargin{2};
            end

            if nargin>3  % third is userFolders
                app.userFolders=varargin{3};  % userfiles, code, user, config, data, log
                if isfield(app.userFolders,'code')&& ~isempty(app.userFolders.code)
                    app.UserIDEditField.Value=app.userFolders.code;
                end
                if isfield(app.userFolders,'data')&& ~isempty(app.userFolders.data)
                    app.defaultName=[app.userFolders.code,app.defaultSuffix]; % name without extension
                    app.defaultPath=app.userFolders.data;
                end   
             end
        if useNNP
            
            app.PMPCBSerialEditField.Enable = 'on';
            app.PMAppRevisionEditField.Enable = 'on';
            
            %Get PM App Revs
            resp = app.nnp.getSwRev(7);
            if length(resp)==1
                app.PMAppRevisionEditField.Value = resp(1);
                app.PMAppRevisionEditField.BackgroundColor = [1 1 1];
            else
                app.PMAppRevisionEditField.Value = Inf;
                app.PMAppRevisionEditField.BackgroundColor = [1 0 0];
            end
            
            %pause(app.pauseTime);
            
            %Get PM Serial number
            resp = app.nnp.getSerial(7);
            if length(resp)==1
                app.PMPCBSerialEditField.Value = resp(1);
                app.PMPCBSerialEditField.BackgroundColor = [1 1 1];
            else
                app.PMPCBSerialEditField.Value = Inf;
                app.PMPCBSerialEditField.BackgroundColor = [1 0 0];
            end
            
            %pause(app.pauseTime);
            app.setNetworkV();
            %pause(app.pauseTime);
            app.setNetworkOn();
            
            app.emgSamp = 1000;
            app.emgbuf = nan(app.emgSamp, 1);
            plot(app.EMGAxes, 1:app.emgSamp, app.emgbuf);
            app.EMGAxes.XLim = [0 1000];
            app.EMGAxes.YLim = [0 255];
            
            app.EMGAxes.Toolbar.Visible = 'off';  %to make the little toolbar at top right disappear.
            disableDefaultInteractivity(app.EMGAxes); %to prevent clicking points with cursor
            
            pause(0.1);
            app.NodeDropDownValueChanged();
            
            app.emgTimer = timer();
            app.emgTimer.Period = 0.048;
            app.emgTimer.ExecutionMode = 'fixedRate';
            app.emgTimer.BusyMode = 'drop';
            app.emgTimer.TimerFcn = @app.updateEMG;
            % app.emgTimer.TimerFcn = @app.updateEMGfile;
                        
            app.CueArray(1,:)=1;
            app.CueArray(2,:)=2;
        end
        app.FolderEditField.Value=app.defaultPath;  % start with current path
        end

        % Value changed function: NodeDropDown
        function NodeDropDownValueChanged(app, event)
            app.node = str2double(app.NodeDropDown.Value);
            
            
            app.BPPCBSerialEditField.Enable = 'on';
            app.BPAppRevisionEditField.Enable = 'on';
            
            %Check module type
            resp = app.nnp.getType(app.node);
            if ~isempty(resp) && ~isequal(resp, '2ch Biopotential Sensor BP-2')
                msgbox('Connected device is not a BP', 'BP Test', 'warn');
            end
            
            %Get RM App Revs
            resp = app.nnp.getSwRev(app.node);
            if length(resp)==1
                app.BPAppRevisionEditField.Value = resp;
                app.BPAppRevisionEditField.BackgroundColor = [1 1 1];
            else
                app.BPAppRevisionEditField.Value = Inf;
                app.BPAppRevisionEditField.BackgroundColor = [1 0 0];
            end
            
            %pause(app.pauseTime);
            
            %Get RM Serial number
            resp = app.nnp.getSerial(app.node);
            if length(resp)==1
                app.BPPCBSerialEditField.Value = resp;
                app.BPPCBSerialEditField.BackgroundColor = [1 1 1];
            else
                app.BPPCBSerialEditField.Value = Inf;
                app.BPPCBSerialEditField.BackgroundColor = [1 0 0];
            end


            %pause(app.pauseTime);
            
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.updateTimer)
                if isa(app.updateTimer,'timer')  %these start as [] which cannot be validated or deleted
                    if isvalid(app.updateTimer)
                        stop(app.updateTimer);
                    end
                    delete(app.updateTimer);
                end
            end
            if ~isempty(app.emgTimer)
                if isa(app.emgTimer,'timer')  %these start as [] which cannot be validated or deleted
                    if isvalid(app.emgTimer)
                        stop(app.emgTimer);
                    end
                    delete(app.emgTimer);
                end
            end
            if ishandle(app.callingFigure) % check that is is valid
                figure(app.callingFigure); % bring to front
            end

            delete(app);
        end

        % Callback function
        function reinitializeButtonPushed(app, event)
            delete(app.nnp);
            app.startupFcn();
        end

        % Value changed function: ViewEMGButton
        function ViewEMGButtonValueChanged(app, event)
            if app.ViewEMGButton.Value
                if app.LogEMGSwitch.Value=="On"
                    app.NewLogFileButtonPushed;
                end
                
                app.startEMG();
                app.ViewEMGButton.Enable = 'on';
                app.GainDropDown.Enable = 'on'; % allow gain changes while viewing
            else
                app.stopEMG();
                if app.IncludeStimButton.Value==true
                    %                     pause(.2);
                    app.stopStim();
                end
            end
        end

        % Value changed function: GainDropDown
        function GainDropDownValueChanged(app, event)
            gain = str2double(app.GainDropDown.Value);
            if ~app.nnp.setBPGains(app.node, gain, gain)
                msgbox('Gains not set')
            end
            
        end

        % Button pushed function: FolderButton
        function FolderButtonPushed(app, event)
            NewLogFolderName(app);
            NewLogFileName(app);
        end

        % Button pushed function: NewLogFile
        function NewLogFileButtonPushed(app, event)
            % if current fle is open, want to close it first
            if exist('app.LFileID','var')==1  %file id exists
                if ~isempty(fopen(app.LFileID)) %file is open
                    fclose(app.LFileID); % close file
                end
            end
            % Get new file name
            NewLogFileName(app);
            % Open new file
            if exist('app.LFileID','var')==1  %file id exists
                if isempty(fopen(app.LFileID)) %file is not open
                    app.LFileID=fopen(app.FullLogName,'a'); % open file in append mode
                end
            else
                app.LFileID=fopen(app.FullLogName,'a'); % open file in append mode
            end
        end

        % Value changed function: LogEMGSwitch
        function LogEMGSwitchValueChanged(app, event)
            value = app.LogEMGSwitch.Value;
            if value=="On"
                app.emgTimer.TimerFcn = @app.updateEMGfile;
                %                 app.LogEMGSwitch.BackgroundColor='g';
                % add a check here for a valid folder and call
                % FolderButtonPushed if not
                if ~isfolder(app.FolderEditField.Value)
                    NewLogFolderName(app);
                end
                % Add check for valid file FID, and see if it is open
                % if not, then check for valid filename and create on if
                % not and open the file in append mode.
                if ~isfile(app.FullLogName)
                    NewLogFileName(app);    % create file name
                end
                % check whether file ID exists
                if exist('app.LFileID','var')==0
                    app.LFileID=fopen(app.FullLogName,'a'); % open file in append mode
                end
                % check whether file is already open
                if isempty(fopen(app.LFileID)) %file is not open
                    app.LFileID=fopen(app.FullLogName,'a'); % open file in append mode
                end
                % not create header
            else
                app.emgTimer.TimerFcn = @app.updateEMG; % this is set at startup
                %                 app.LogEMGSwitch.BackgroundColor=[.96,.96,.96];
                
                % Note that I don't close the file here because I may want
                % to append to it.
            end
        end

        % Button pushed function: ShowLastButton
        function ShowLastButtonPushed(app, event)
            % pass the file ID to a new window and display the raw data.
            % I if I could also pass filename, channel, gain, but start
            % with just the file ID and see if I can read the data first
            % and zoom properly.
            if ~isempty(app.FullLogName)
                app.FullLogNameEditField.Value=app.FullLogName;
            end
            if ~isempty(app.LFileID)
                fclose(app.LFileID);
            end
            
            emgDisplayRawCue(app.FullLogName);
        end

        % Value changed function: IncludeStimButton
        function IncludeStimButtonValueChanged(app, event)
            value = app.IncludeStimButton.Value;
            if value == true
                app.IncludeStimButton.Text='On';
                app.IncludeStimButton.BackgroundColor=[.6,1,.6];
            else
                app.IncludeStimButton.Text='Off';
                app.IncludeStimButton.BackgroundColor=[1,.6,.6];
            end
            
        end

        % Button pushed function: LoadfromFileButton
        function LoadfromFileButtonPushed(app, event)
            [filename, filepath] = uigetfile('.mat','Select Profiler History file');
            if filename
                ld = load([filepath filename]);
                data = ld.data;
                %Error checking on input file
                if size(data, 2) ~= 8
                    msgbox('Profiler history file does not have 8 columns of data', 'History File', 'error')
                    return
                end
                if size(data,1) == 0
                    msgbox('Profiler history file contains no rows', 'History File', 'error')
                    return
                end
                selector = cell2mat(data(:,1));
                if ~isempty(selector)
                    msgbox('Column 1 (Selector) must be all empty', 'History File', 'error')
                    return
                end
                active = cell2mat(data(:,2));
                if ~islogical(active)
                    msgbox('Column 2 (Active) must be all logicals', 'History File', 'error')
                    return
                end
                node = cell2mat(data(:,3));
                if any(~ismember(node, 1:127))
                    msgbox('Column 3 (Node) must be 1-127', 'History File', 'error')
                    return
                end
                ch = cell2mat(data(:,4));
                if any(~ismember(ch, 1:4))
                    msgbox('Column 4 (Ch) must be 1-4', 'History File', 'error')
                    return
                end
                pw = cell2mat(data(:,5));
                if any(~ismember(pw, 0:255))
                    msgbox('Column 5 (PW) must be 0-255', 'History File', 'error')
                    return
                end
                pa = cell2mat(data(:,6));
                if any(~ismember(pa, 0:200))
                    msgbox('Column 6 (PA) must be 0-200', 'History File', 'error')
                    return
                end
                app.UITable.Data = data;
                figure(app.UIFigure); %restore focus to main figure
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            row = event.Indices(1);
            col = event.Indices(2);
            app.lastrowselected=row;
            if col == 1 && app.stimOn == false
                data = app.UITable.Data(row,:);
                node = data{3};
                ch = data{4};
                pw = data{5};
                pa = data{6};
                
                app.NodeEditField.Value = node;
                app.ChanEditField.Value = ch;
                app.PAEditField.Value = pa/10;
                app.PWEditField.Value = pw;
            end
        end

        % Button pushed function: StopNetButton
        function StopNetButtonPushed(app, event)
            %emergency stop button in case the command gets stopped somewhere
            %in the Network.  Thei turns off the Network at the PM to stop
            %stim.  What it doesn't do is corect for radio or communication
            %errors between Matlab and the PM
            app.nnp.enterWaiting();  %needs to be in waiting before network can be turned off, resp=1 is success
            pause(.1);
            app.nnp.networkOff();
            app.stimOn=false;
            %             pause(.2);
            %             app.stopEMG();
            %             pause(.2);
            %             app.stopStim();
            app.StimPanel.BackgroundColor=[.94,.94,.94];
            % if I turn off the network, I also need to stop ViewEMG
            app.ViewEMGButton.Value=false;
            app.stopEMG();
        end

        % Value changed function: UseButton
        function UseButtonValueChanged(app, event)
            value = app.UseButton.Value;
            app.CueLabel.Visible=value;
            if value % set to default level when starting
                app.Cue=1;
            else
                app.Cue=0;
            end
           
            app.CueChange(app.Cue);
        end

        % Value changed function: DelayEditField
        function DelayEditFieldValueChanged(app, event)
            value = app.DelayEditField.Value;
            app.CueParameters(1)=round(value/.048,0);
        end

        % Value changed function: ContractEditField
        function ContractEditFieldValueChanged(app, event)
            value = app.ContractEditField.Value;
            app.CueParameters(3)=round(value/.048,0);
        end

        % Value changed function: RelaxEditField
        function RelaxEditFieldValueChanged(app, event)
            value = app.RelaxEditField.Value;
            app.CueParameters(2)=round(value/.048,0);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MES Raw Recording';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PMPCBSerialEditFieldLabel = uilabel(app.UIFigure);
            app.PMPCBSerialEditFieldLabel.Text = 'PM PCB Serial #';
            app.PMPCBSerialEditField = uieditfield(app.UIFigure, 'numeric');
            app.PMPCBSerialEditField.Editable = 'off';
            app.PMPCBSerialEditField.Value = Inf;
            app.PMAppRevisionLabel = uilabel(app.UIFigure);
            app.PMAppRevisionLabel.Text = 'PM App Revision';
            app.PMAppRevisionEditField = uieditfield(app.UIFigure, 'numeric');
            app.PMAppRevisionEditField.Editable = 'off';
            app.PMAppRevisionEditField.Value = Inf;
            app.NodeDropDownLabel = uilabel(app.UIFigure);
            app.NodeDropDownLabel.Text = 'Node';
            app.NodeDropDown = uidropdown(app.UIFigure);
            app.NodeDropDown.Items = {'1', '2', '3', '4', '5', '6', '9', '10'};
            app.NodeDropDown.ValueChangedFcn = createCallbackFcn(app, @NodeDropDownValueChanged, true);
            app.NodeDropDown.Value = '9';
            app.BPPCBSerialEditFieldLabel = uilabel(app.UIFigure);
            app.BPPCBSerialEditFieldLabel.Text = 'BP PCB Serial #';
            app.BPPCBSerialEditField = uieditfield(app.UIFigure, 'numeric');
            app.BPPCBSerialEditField.Editable = 'off';
            app.BPPCBSerialEditField.Value = Inf;
            app.BPAppRevisionEditFieldLabel = uilabel(app.UIFigure);
            app.BPAppRevisionEditFieldLabel.Text = 'BP App Revision';
            app.BPAppRevisionEditField = uieditfield(app.UIFigure, 'numeric');
            app.BPAppRevisionEditField.Editable = 'off';
            app.BPAppRevisionEditField.Value = Inf;
            app.PanelEMG = uipanel(app.UIFigure);
            app.EMGAxes = uiaxes(app.PanelEMG);
            title(app.EMGAxes, 'Raw EMG')
            xlabel(app.EMGAxes, 'time (ms)')
            ylabel(app.EMGAxes, 'EMG (ADU)')
            app.ChannelDropDown = uidropdown(app.PanelEMG);
            app.ChannelDropDown.Items = {'Channel 1', 'Channel 2', 'Offset 1', 'Offset 2'};
            app.ChannelDropDown.Value = 'Channel 1';
            app.GainDropDown = uidropdown(app.PanelEMG);
            app.GainDropDown.Items = {'Gain 190x (2)', 'Gain 300x (4)', 'Gain 475x (6)', 'Gain 750x (8)', 'Gain 1500x (11)', 'Gain 3000x (14)', 'Gain Level 20'};
            app.GainDropDown.ItemsData = {'2', '4', '6', '8', '11', '14', '20'};
            app.GainDropDown.ValueChangedFcn = createCallbackFcn(app, @GainDropDownValueChanged, true);
            app.GainDropDown.Value = '4';
            app.ViewEMGButton = uibutton(app.PanelEMG, 'state');
            app.ViewEMGButton.ValueChangedFcn = createCallbackFcn(app, @ViewEMGButtonValueChanged, true);
            app.ViewEMGButton.Text = 'View EMG';
            app.TabGroup = uitabgroup(app.PanelEMG);
            app.CuesTab = uitab(app.TabGroup);
            app.CuesTab.Title = 'Cues';
            app.CueLabel = uilabel(app.CuesTab);
            app.CueLabel.Text = '';
            app.UseButton = uibutton(app.CuesTab, 'state');
            app.UseButton.ValueChangedFcn = createCallbackFcn(app, @UseButtonValueChanged, true);
            app.UseButton.Text = 'Use';
            app.SetTab = uitab(app.TabGroup);
            app.SetTab.Title = 'Set';
            app.DelayEditFieldLabel = uilabel(app.SetTab);
            app.DelayEditFieldLabel.Text = 'Delay';
            app.DelayEditField = uieditfield(app.SetTab, 'numeric');
            app.DelayEditField.ValueChangedFcn = createCallbackFcn(app, @DelayEditFieldValueChanged, true);
            app.DelayEditField.Value = 1.5;
            app.ContractEditFieldLabel = uilabel(app.SetTab);
            app.ContractEditFieldLabel.Text = 'Contract';
            app.ContractEditField = uieditfield(app.SetTab, 'numeric');
            app.ContractEditField.ValueChangedFcn = createCallbackFcn(app, @ContractEditFieldValueChanged, true);
            app.ContractEditField.Value = 2;
            app.RelaxEditFieldLabel = uilabel(app.SetTab);
            app.RelaxEditFieldLabel.Text = 'Relax';
            app.RelaxEditField = uieditfield(app.SetTab, 'numeric');
            app.RelaxEditField.ValueChangedFcn = createCallbackFcn(app, @RelaxEditFieldValueChanged, true);
            app.RelaxEditField.Value = 3;
            app.CueTimesSecondsLabel = uilabel(app.SetTab);
            app.CueTimesSecondsLabel.Text = 'Cue Times, Seconds';
            app.StimPanel = uipanel(app.UIFigure);
            app.StimPanel.Title = 'Stimulation';
            app.NodeLabel = uilabel(app.StimPanel);
            app.NodeLabel.Text = 'Node';
            app.NodeEditField = uieditfield(app.StimPanel, 'numeric');
            app.NodeEditField.Value = 1;
            app.ChannelLabel = uilabel(app.StimPanel);
            app.ChannelLabel.Text = 'Channel';
            app.ChanEditField = uieditfield(app.StimPanel, 'numeric');
            app.ChanEditField.Value = 1;
            app.PWLabel = uilabel(app.StimPanel);
            app.PWLabel.Text = 'PW';
            app.PWEditField = uieditfield(app.StimPanel, 'numeric');
            app.AmpLabel = uilabel(app.StimPanel);
            app.AmpLabel.Text = 'Amp';
            app.PAEditField = uieditfield(app.StimPanel, 'numeric');
            app.PAEditField.Value = 20;
            app.IncludeStimButton = uibutton(app.StimPanel, 'state');
            app.IncludeStimButton.ValueChangedFcn = createCallbackFcn(app, @IncludeStimButtonValueChanged, true);
            app.IncludeStimButton.Text = 'Off';
            app.IncludeStimLabel = uilabel(app.StimPanel);
            app.IncludeStimLabel.Text = {'Include'; 'Stim'};
            app.UITable = uitable(app.StimPanel);
            app.UITable.ColumnName = {'Select'; 'Active'; 'Node'; 'Ch'; 'PW'; 'PA'; 'Reg'; 'Notes'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = [false true true true true true false true];
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.LoadfromFileButton = uibutton(app.StimPanel, 'push');
            app.LoadfromFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfromFileButtonPushed, true);
            app.LoadfromFileButton.Text = {'Load'; 'from File'};
            app.VOSEditFieldLabel = uilabel(app.StimPanel);
            app.VOSEditFieldLabel.Text = 'v';
            app.VOSEditField = uieditfield(app.StimPanel, 'numeric');
            app.VOSEditField.Value = 34;
            app.ErrorLampLabel = uilabel(app.StimPanel);
            app.ErrorLampLabel.Text = 'Error';
            app.ErrorLamp = uilamp(app.StimPanel);
            app.ErrorLabel = uilabel(app.StimPanel);
            app.ErrorLabel.Text = 'Error';
            app.ErrorEditField = uieditfield(app.StimPanel, 'text');
            app.StopNetButton = uibutton(app.StimPanel, 'push');
            app.StopNetButton.ButtonPushedFcn = createCallbackFcn(app, @StopNetButtonPushed, true);
            app.StopNetButton.Text = 'StopNet';
            app.SetVOSminCheckBox = uicheckbox(app.StimPanel);
            app.SetVOSminCheckBox.Text = 'Set VOSmin';
            app.VnetEditFieldLabel = uilabel(app.UIFigure);
            app.VnetEditFieldLabel.Text = 'Vnet';
            app.VnetEditField = uieditfield(app.UIFigure, 'numeric');
            app.VnetEditField.Value = 8.5;
            app.LogEMGPanel = uipanel(app.UIFigure);
            app.LogEMGPanel.Title = 'Log EMG';
            app.FullLogNameEditField = uieditfield(app.LogEMGPanel, 'text');
            app.LogEMGLabel = uilabel(app.LogEMGPanel);
            app.LogEMGLabel.Text = 'LogEMG';
            app.LogEMGSwitch = uiswitch(app.LogEMGPanel, 'slider');
            app.LogEMGSwitch.ValueChangedFcn = createCallbackFcn(app, @LogEMGSwitchValueChanged, true);
            app.LogFilenameEditField = uieditfield(app.LogEMGPanel, 'text');
            app.LogFilenameEditField.Editable = 'off';
            app.FolderButton = uibutton(app.LogEMGPanel, 'push');
            app.FolderButton.ButtonPushedFcn = createCallbackFcn(app, @FolderButtonPushed, true);
            app.FolderButton.Text = 'Folder';
            app.FolderEditField = uieditfield(app.LogEMGPanel, 'text');
            app.FolderEditField.Editable = 'off';
            app.NewLogFile = uibutton(app.LogEMGPanel, 'push');
            app.NewLogFile.ButtonPushedFcn = createCallbackFcn(app, @NewLogFileButtonPushed, true);
            app.NewLogFile.Text = 'New File';
            app.ShowLastButton = uibutton(app.LogEMGPanel, 'push');
            app.ShowLastButton.ButtonPushedFcn = createCallbackFcn(app, @ShowLastButtonPushed, true);
            app.ShowLastButton.Text = 'Show Last';
            app.UserIDEditField = uieditfield(app.LogEMGPanel, 'text');
            app.UserIDEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = emgRecordRawCue(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end