classdef emgTestDataGraphTicToc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EMGTestDataUIFigure        matlab.ui.Figure
        ViewEMGButton              matlab.ui.control.StateButton
        MessagesLabel              matlab.ui.control.Label
        ClearMessageButton         matlab.ui.control.Button
        Messages                   matlab.ui.control.TextArea
        TestDataButton             matlab.ui.control.Button
        BPEMGButton                matlab.ui.control.Button
        EMG2Panel                  matlab.ui.container.Panel
        TwitchLabel_2              matlab.ui.control.Label
        RampLabel_2                matlab.ui.control.Label
        StepLabel_2                matlab.ui.control.Label
        mtypesentEditField_2       matlab.ui.control.NumericEditField
        mtypesentEditField_2Label  matlab.ui.control.Label
        MESButton11_2              matlab.ui.control.Button
        MESButton10_2              matlab.ui.control.Button
        MESButton9_2               matlab.ui.control.Button
        MESButton8_2               matlab.ui.control.Button
        MESButton7_2               matlab.ui.control.Button
        MESButton6_2               matlab.ui.control.Button
        MESButton5_2               matlab.ui.control.Button
        MESButton4_2               matlab.ui.control.Button
        MESButton3_2               matlab.ui.control.Button
        MESButton2_2               matlab.ui.control.Button
        MESButton1_2               matlab.ui.control.Button
        MESButton0_2               matlab.ui.control.Button
        HoldLabel_2                matlab.ui.control.Label
        EMG1Panel                  matlab.ui.container.Panel
        TwitchLabel                matlab.ui.control.Label
        RampLabel                  matlab.ui.control.Label
        StepLabel                  matlab.ui.control.Label
        mtypesentEditField         matlab.ui.control.NumericEditField
        mtypesentEditFieldLabel    matlab.ui.control.Label
        MESButton11                matlab.ui.control.Button
        MESButton10                matlab.ui.control.Button
        MESButton9                 matlab.ui.control.Button
        MESButton8                 matlab.ui.control.Button
        MESButton7                 matlab.ui.control.Button
        MESButton6                 matlab.ui.control.Button
        MESButton5                 matlab.ui.control.Button
        MESButton4                 matlab.ui.control.Button
        MESButton3                 matlab.ui.control.Button
        MESButton2                 matlab.ui.control.Button
        MESButton1                 matlab.ui.control.Button
        MESButton0                 matlab.ui.control.Button
        HoldLabel                  matlab.ui.control.Label
        EMGAxes2                   matlab.ui.control.UIAxes
        EMGAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        callingFigure=[]; %handle to calling figure
        nnp=[]; % nnpapi
        callingPosition=[];

        % buffers 1
        plotbuf = [];
        emgbuf = [];
        pWFL=[]; % WFL plot 1
        
        % buffers 2
        plotbuf2 = [];
        emgbuf2 = [];
        pWFL2=[]; % WFL plot 2
        
        % instead of using the timer function, I am going to use tic and
        % toc, which are also timers, but maybe used in a different manner
        emgTimer = [];
        tReads=[]; % this will be the timer between reads in sec
        tSpacing=0.10; % 100 ms spacing between reads

        emgsamp = 50; % start with 5 seconds, may be too slow

        % not implemented yet
        plotCycle=2; %plot every Nth cycle, makeing this 3Hz update
        cycle=0; % cycle counter if plotting every N cycles
        plotThis=0; % use if plotting every N cycles
        oldTime=[]; % use if adding a time to the data
        lastcounter=0; % use if also reading counter
    end
    
    methods (Access = private)

        function ClearMessage(app)
            app.Messages.Value=''; % clear messages
        end

        function TopMessage(app,message)
            app.Messages.Value=vertcat(message, string(app.Messages.Value)); % put new message on top
        end
        
        function startTestData(app)
            % When starting, set 1F53.A and B to zero first
            message='Start TestData Failed'; % if any fail, start, use this message
            resp=app.nnp.write(7,'1F53',10,uint8([0,0]),2,'uint8');
            if resp==0
                % Then start script 1
                resp2=app.nnp.nmt(7,'8D',1); % Run MES TestData script
                if resp2==hex2dec('8D')
                    % Set 1F53.9 to 1
                    resp3=app.nnp.write(7,'1F53',9,uint8(1),'uint8');
                    if resp3==0
                        message='TestData Started';
                        % enable both panels
                        app.EMG1Panel.Enable='on';
                        app.EMG2Panel.Enable='on';
                    end
                end
            end
            app.TopMessage(message);
         end
        
        function startBPData(app)
            % When switching back to BPData, for bookkeeping
            % Disable the panels
            app.EMG1Panel.Enable='off';
            app.EMG2Panel.Enable='off';

            % send 0 to 1F53.A and B
            message='Stop TestData Failed'; % if any fail, start, use this message
            resp=app.nnp.write(7,'1F53',10,uint8([0,0]),2,'uint8');
            if resp==0
                % Set 1F53.9 to 0
                resp3=app.nnp.write(7,'1F53',9,uint8(0),'uint8');
                if resp3==0
                    pause(.5);% wait for rPDO to be set back
                    resp2=app.nnp.nmt(7,'AE',1); % Run MES TestData script
                    if resp2==hex2dec('AE')
                        message='TestData Stopped';
                    end
                end
            end
            app.TopMessage(message);
            % Do I want to stop the script here? Or stop when closing
            % figure?  Probably best to stop script
        end
        
        
        function setMESType(app,chan,mtype)
            % chan is MES1 or MES2
            % I could set them both with one message, but probably will want
            % to set them independently anyway
            % mtype is number from 0-11
            % Hold	    0	Low	1	    Med	2	High
            % Step	    3	Low	4	    Med	5	High
            % Ramp	    6	Up/Down	7	Up	8	Down
            % Twitch	9	Short	10	Long	11	Doublet
            % Send mtype to either 1F53.A or 1F53.B
            if chan==1 || chan==2 % chan has be be either 1 or 2
                subindex=9+chan;
                resp=app.nnp.write(7,'1F53',subindex,uint8(mtype),'uint8');
                if resp~=0 % error writing
                    app.TopMessage('Error writing mtype'); % only respond if fails
                end
            end
        end
        
        function startEMG(app)
%             start(app.emgTimer);
%             message='start EMG timer';
%             app.TopMessage(message);
        end
        
        function stopEMG(app)
%             stop(app.emgTimer);
%             message='stop EMG timer';
%             app.TopMessage(message);
        end
        
        function updateEMG(app)
            % updateEMG(app, ~, ~)
            app.tReads=tic;
            % first read
            resp = app.nnp.read(7, '1F53', 46,2, 'uint8');

            if(length(resp)==2) %valid packet
                % update the buffers
                % I could start at index 26 which is the counter that is
                % updated by the admin script, but that would require the admin
                % script to be running and read a block of 22 elements

                % keep all buffers up to date, even if not plotting
                app.emgbuf(1:end-1) = app.emgbuf(2:end);
                app.emgbuf(end)=resp(1);
                app.emgbuf2(1:end-1) = app.emgbuf2(2:end);
                app.emgbuf2(end)=resp(2);

                % check the counter to see whether to update the plot

                % plot the data (first plot)
                app.pWFL.YData = app.emgbuf;
                app.pWFL2.YData = app.emgbuf2;
                app.cycle=1; % first cycle
%                 drawnow limitrate;
            end

            while app.ViewEMGButton.Value % as long as button true
                d=toc(app.tReads); % time since last read
               
                if d>app.tSpacing
                    app.tReads=tic; % reset the tic
                    resp = app.nnp.read(7, '1F53', 46,2, 'uint8');

                    if(length(resp)==2) %valid packet
                        % update the buffers
                        % I could start at index 26 which is the counter that is
                        % updated by the admin script, but that would require the admin
                        % script to be running and read a block of 22 elements

                        % keep all buffers up to date, even if not plotting
                        app.emgbuf(1:end-1) = app.emgbuf(2:end);
                        app.emgbuf(end)=resp(1);
                        app.emgbuf2(1:end-1) = app.emgbuf2(2:end);
                        app.emgbuf2(end)=resp(2);

                        % check the counter to see whether to update the plot
                        if app.cycle>=app.plotCycle
                            % plot the data
                            app.pWFL.YData = app.emgbuf;
                            app.pWFL2.YData = app.emgbuf2;
                            app.cycle=1;
                        else
                            app.cycle=app.cycle+1;
                        end
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin<2
                NNP=[];
            else
                NNP=varargin{1};
            end
            if isempty(NNP)
                app.nnp = NNPAPI();
                if isempty(app.nnp.port)
                    msgbox('No port chosen for NNP Access Point')
                    delete(app.nnp)
                    return
                end
            else
                app.nnp = NNP;
            end
            if nargin>2
                app.callingFigure=varargin{2};
                cp=app.callingFigure.Position; % get position of the figure that called
                mp=app.EMGTestDataUIFigure.Position; % get current position
                xpos=cp(1)+cp(3)+1;
                ypos=cp(2);
                mp=[xpos,ypos,mp(3:4)];
                app.EMGTestDataUIFigure.Position=mp;               
            end
            
%             app.emgTimer = timer();
%             app.emgTimer.Period = 0.1;
%             app.emgTimer.ExecutionMode = 'fixedRate';
%             app.emgTimer.BusyMode = 'drop';
%             app.emgTimer.TimerFcn = @app.updateEMG;
            
            %First plot
            app.emgbuf = nan(app.emgsamp, 1);
            app.emgbuf2 = nan(app.emgsamp, 1);           
            % Second plot
            app.pWFL=plot(app.EMGAxes, 1:app.emgsamp, app.emgbuf);
            app.pWFL2=plot(app.EMGAxes2, 1:app.emgsamp, app.emgbuf2);
%             linkaxes([app.EMGAxes,app.EMGAxes],'x');
            app.EMGAxes.Toolbar.Visible = 'off';
            app.EMGAxes2.Toolbar.Visible = 'off';
            disableDefaultInteractivity(app.EMGAxes);
            disableDefaultInteractivity(app.EMGAxes2);
        end

        % Button pushed function: MESButton0
        function MESButton0Pushed(app, event)
            app.setMESType(1,0);
            app.mtypesentEditField.Value=0;
        end

        % Button pushed function: MESButton1
        function MESButton1Pushed(app, event)
         app.setMESType(1,1);
         app.mtypesentEditField.Value=1;   
         pause(.5);
         app.mtypesentEditField.Value=0; 
        end

        % Button pushed function: MESButton2
        function MESButton2Pushed(app, event)
         app.setMESType(1,2);
         app.mtypesentEditField.Value=2;   
         pause(.5);
         app.mtypesentEditField.Value=0;     
        end

        % Button pushed function: MESButton3
        function MESButton3Pushed(app, event)
            app.setMESType(1,3);
            app.mtypesentEditField.Value=3;
            pause(.5);
            app.mtypesentEditField.Value=0;
        end

        % Button pushed function: MESButton4
        function MESButton4Pushed(app, event)
            app.setMESType(1,4);
            app.mtypesentEditField.Value=4;
            pause(.5);
            app.mtypesentEditField.Value=0;
        end

        % Button pushed function: MESButton5
        function MESButton5Pushed(app, event)
            app.setMESType(1,5);
            app.mtypesentEditField.Value=5;
            pause(.5);
            app.mtypesentEditField.Value=0;
        end

        % Button pushed function: MESButton6
        function MESButton6Pushed(app, event)
            app.setMESType(1,6);
            app.mtypesentEditField.Value=6;
            pause(.5);
            app.mtypesentEditField.Value=0;
        end

        % Button pushed function: MESButton7
        function MESButton7Pushed(app, event)
            app.setMESType(1,7);
            app.mtypesentEditField.Value=7;
            pause(.5);
            app.mtypesentEditField.Value=0;
        end

        % Button pushed function: MESButton8
        function MESButton8Pushed(app, event)
            app.setMESType(1,8);
            app.mtypesentEditField.Value=8;
            pause(.5);
            app.mtypesentEditField.Value=0;
        end

        % Button pushed function: MESButton9
        function MESButton9Pushed(app, event)
            app.setMESType(1,9);
            app.mtypesentEditField.Value=9;
            pause(.5);
            app.mtypesentEditField.Value=0;
        end

        % Button pushed function: MESButton10
        function MESButton10Pushed(app, event)
            app.setMESType(1,10);
            app.mtypesentEditField.Value=10;
            pause(.5);
            app.mtypesentEditField.Value=0;
        end

        % Button pushed function: MESButton11
        function MESButton11Pushed(app, event)
            app.setMESType(1,11);
            app.mtypesentEditField.Value=11;
            pause(.5);
            app.mtypesentEditField.Value=0;
        end

        % Button pushed function: MESButton0_2
        function MESButton0_2Pushed(app, event)
           app.setMESType(2,0);
           app.mtypesentEditField_2.Value=0;
        end

        % Button pushed function: MESButton1_2
        function MESButton1_2Pushed(app, event)
            app.setMESType(2,1);
           app.mtypesentEditField_2.Value=1;
            pause(.5);
            app.mtypesentEditField_2.Value=0;
        end

        % Button pushed function: MESButton2_2
        function MESButton2_2Pushed(app, event)
            app.setMESType(2,2);
            app.mtypesentEditField_2.Value=2;
            pause(.5);
            app.mtypesentEditField_2.Value=0;
        end

        % Button pushed function: MESButton3_2
        function MESButton3_2Pushed(app, event)
            app.setMESType(2,3);
            app.mtypesentEditField_2.Value=3;
            pause(.5);
            app.mtypesentEditField_2.Value=0;
        end

        % Button pushed function: MESButton4_2
        function MESButton4_2Pushed(app, event)
            app.setMESType(2,4);
            app.mtypesentEditField_2.Value=4;
            pause(.5);
            app.mtypesentEditField_2.Value=0;
        end

        % Button pushed function: MESButton5_2
        function MESButton5_2Pushed(app, event)
            app.setMESType(2,5);
            app.mtypesentEditField_2.Value=5;
            pause(.5);
            app.mtypesentEditField_2.Value=0;
        end

        % Button pushed function: MESButton6_2
        function MESButton6_2Pushed(app, event)
            app.setMESType(2,6);
            app.mtypesentEditField_2.Value=6;
            pause(.5);
            app.mtypesentEditField_2.Value=0;
        end

        % Button pushed function: MESButton7_2
        function MESButton7_2Pushed(app, event)
            app.setMESType(2,7);
            app.mtypesentEditField_2.Value=7;
            pause(.5);
            app.mtypesentEditField_2.Value=0;
        end

        % Button pushed function: MESButton8_2
        function MESButton8_2Pushed(app, event)
            app.setMESType(2,8);
            app.mtypesentEditField_2.Value=8;
            pause(.5);
            app.mtypesentEditField_2.Value=0;
        end

        % Button pushed function: MESButton9_2
        function MESButton9_2Pushed(app, event)
            app.setMESType(2,9);
            app.mtypesentEditField_2.Value=9;
            pause(.5);
            app.mtypesentEditField_2.Value=0;
        end

        % Button pushed function: MESButton10_2
        function MESButton10_2Pushed(app, event)
            app.setMESType(2,10);
            app.mtypesentEditField_2.Value=10;
            pause(.5);
            app.mtypesentEditField_2.Value=0;
        end

        % Button pushed function: MESButton11_2
        function MESButton11_2Pushed(app, event)
            app.setMESType(2,11);
            app.mtypesentEditField_2.Value=11;
            pause(.5);
            app.mtypesentEditField_2.Value=0;
        end

        % Close request function: EMGTestDataUIFigure
        function EMGTestDataUIFigureCloseRequest(app, event)
            if ishandle(app.callingFigure) % check that is is valid
                figure(app.callingFigure); % bring to front
            end
            delete(app)

        end

        % Button pushed function: BPEMGButton
        function BPEMGButtonPushed(app, event)
            app.startBPData();
        end

        % Button pushed function: TestDataButton
        function TestDataButtonPushed(app, event)
            app.startTestData();
        end

        % Button pushed function: ClearMessageButton
        function ClearMessageButtonPushed(app, event)
            app.ClearMessage();
        end

        % Value changed function: ViewEMGButton
        function ViewEMGButtonValueChanged(app, event)
            value = app.ViewEMGButton.Value;
            if value
%                 app.startEMG();
                  app.updateEMG();
            else
%                 app.stopEMG();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EMGTestDataUIFigure = uifigure('Visible', 'off');
            app.EMGTestDataUIFigure.Name = 'EMG Test Data';
            app.EMGTestDataUIFigure.CloseRequestFcn = createCallbackFcn(app, @EMGTestDataUIFigureCloseRequest, true);
            app.EMGAxes = uiaxes(app.EMGTestDataUIFigure);
            title(app.EMGAxes, 'EMG1')
            xlabel(app.EMGAxes, 'Samples 10 Hz')
            ylabel(app.EMGAxes, 'WFL1')
            zlabel(app.EMGAxes, 'Z')
            app.EMGAxes.XLim = [0 50];
            app.EMGAxes.YLim = [0 255];
            app.EMGAxes2 = uiaxes(app.EMGTestDataUIFigure);
            title(app.EMGAxes2, 'EMG2')
            xlabel(app.EMGAxes2, 'Samples 10 Hz')
            ylabel(app.EMGAxes2, 'WFL1')
            zlabel(app.EMGAxes2, 'Z')
            app.EMGAxes2.XLim = [0 50];
            app.EMGAxes2.YLim = [0 255];
            app.EMG1Panel = uipanel(app.EMGTestDataUIFigure);
            app.EMG1Panel.Enable = 'off';
            app.EMG1Panel.Title = 'EMG 1';
            app.HoldLabel = uilabel(app.EMG1Panel);
            app.HoldLabel.Text = 'Hold';
            app.MESButton0 = uibutton(app.EMG1Panel, 'push');
            app.MESButton0.ButtonPushedFcn = createCallbackFcn(app, @MESButton0Pushed, true);
            app.MESButton0.Text = 'Low';
            app.MESButton1 = uibutton(app.EMG1Panel, 'push');
            app.MESButton1.ButtonPushedFcn = createCallbackFcn(app, @MESButton1Pushed, true);
            app.MESButton1.Text = 'Med';
            app.MESButton2 = uibutton(app.EMG1Panel, 'push');
            app.MESButton2.ButtonPushedFcn = createCallbackFcn(app, @MESButton2Pushed, true);
            app.MESButton2.Text = 'High';
            app.MESButton3 = uibutton(app.EMG1Panel, 'push');
            app.MESButton3.ButtonPushedFcn = createCallbackFcn(app, @MESButton3Pushed, true);
            app.MESButton3.Text = 'Low';
            app.MESButton4 = uibutton(app.EMG1Panel, 'push');
            app.MESButton4.ButtonPushedFcn = createCallbackFcn(app, @MESButton4Pushed, true);
            app.MESButton4.Text = 'Med';
            app.MESButton5 = uibutton(app.EMG1Panel, 'push');
            app.MESButton5.ButtonPushedFcn = createCallbackFcn(app, @MESButton5Pushed, true);
            app.MESButton5.Text = 'High';
            app.MESButton6 = uibutton(app.EMG1Panel, 'push');
            app.MESButton6.ButtonPushedFcn = createCallbackFcn(app, @MESButton6Pushed, true);
            app.MESButton6.Text = 'Up Down';
            app.MESButton7 = uibutton(app.EMG1Panel, 'push');
            app.MESButton7.ButtonPushedFcn = createCallbackFcn(app, @MESButton7Pushed, true);
            app.MESButton7.Text = 'Up hold';
            app.MESButton8 = uibutton(app.EMG1Panel, 'push');
            app.MESButton8.ButtonPushedFcn = createCallbackFcn(app, @MESButton8Pushed, true);
            app.MESButton8.Text = 'Down hold';
            app.MESButton9 = uibutton(app.EMG1Panel, 'push');
            app.MESButton9.ButtonPushedFcn = createCallbackFcn(app, @MESButton9Pushed, true);
            app.MESButton9.Text = 'Short';
            app.MESButton10 = uibutton(app.EMG1Panel, 'push');
            app.MESButton10.ButtonPushedFcn = createCallbackFcn(app, @MESButton10Pushed, true);
            app.MESButton10.Text = 'Long';
            app.MESButton11 = uibutton(app.EMG1Panel, 'push');
            app.MESButton11.ButtonPushedFcn = createCallbackFcn(app, @MESButton11Pushed, true);
            app.MESButton11.Text = 'Doublet';
            app.mtypesentEditFieldLabel = uilabel(app.EMG1Panel);
            app.mtypesentEditFieldLabel.Text = 'mtype sent';
            app.mtypesentEditField = uieditfield(app.EMG1Panel, 'numeric');
            app.StepLabel = uilabel(app.EMG1Panel);
            app.StepLabel.Text = 'Step';
            app.RampLabel = uilabel(app.EMG1Panel);
            app.RampLabel.Text = 'Ramp';
            app.TwitchLabel = uilabel(app.EMG1Panel);
            app.TwitchLabel.Text = 'Twitch';
            app.EMG2Panel = uipanel(app.EMGTestDataUIFigure);
            app.EMG2Panel.Enable = 'off';
            app.EMG2Panel.Title = 'EMG 2';
            app.HoldLabel_2 = uilabel(app.EMG2Panel);
            app.HoldLabel_2.Text = 'Hold';
            app.MESButton0_2 = uibutton(app.EMG2Panel, 'push');
            app.MESButton0_2.ButtonPushedFcn = createCallbackFcn(app, @MESButton0_2Pushed, true);
            app.MESButton0_2.Text = 'Low';
            app.MESButton1_2 = uibutton(app.EMG2Panel, 'push');
            app.MESButton1_2.ButtonPushedFcn = createCallbackFcn(app, @MESButton1_2Pushed, true);
            app.MESButton1_2.Text = 'Med';
            app.MESButton2_2 = uibutton(app.EMG2Panel, 'push');
            app.MESButton2_2.ButtonPushedFcn = createCallbackFcn(app, @MESButton2_2Pushed, true);
            app.MESButton2_2.Text = 'High';
            app.MESButton3_2 = uibutton(app.EMG2Panel, 'push');
            app.MESButton3_2.ButtonPushedFcn = createCallbackFcn(app, @MESButton3_2Pushed, true);
            app.MESButton3_2.Text = 'Low';
            app.MESButton4_2 = uibutton(app.EMG2Panel, 'push');
            app.MESButton4_2.ButtonPushedFcn = createCallbackFcn(app, @MESButton4_2Pushed, true);
            app.MESButton4_2.Text = 'Med';
            app.MESButton5_2 = uibutton(app.EMG2Panel, 'push');
            app.MESButton5_2.ButtonPushedFcn = createCallbackFcn(app, @MESButton5_2Pushed, true);
            app.MESButton5_2.Text = 'High';
            app.MESButton6_2 = uibutton(app.EMG2Panel, 'push');
            app.MESButton6_2.ButtonPushedFcn = createCallbackFcn(app, @MESButton6_2Pushed, true);
            app.MESButton6_2.Text = 'Up Down';
            app.MESButton7_2 = uibutton(app.EMG2Panel, 'push');
            app.MESButton7_2.ButtonPushedFcn = createCallbackFcn(app, @MESButton7_2Pushed, true);
            app.MESButton7_2.Text = 'Up hold';
            app.MESButton8_2 = uibutton(app.EMG2Panel, 'push');
            app.MESButton8_2.ButtonPushedFcn = createCallbackFcn(app, @MESButton8_2Pushed, true);
            app.MESButton8_2.Text = 'Down hold';
            app.MESButton9_2 = uibutton(app.EMG2Panel, 'push');
            app.MESButton9_2.ButtonPushedFcn = createCallbackFcn(app, @MESButton9_2Pushed, true);
            app.MESButton9_2.Text = 'Short';
            app.MESButton10_2 = uibutton(app.EMG2Panel, 'push');
            app.MESButton10_2.ButtonPushedFcn = createCallbackFcn(app, @MESButton10_2Pushed, true);
            app.MESButton10_2.Text = 'Long';
            app.MESButton11_2 = uibutton(app.EMG2Panel, 'push');
            app.MESButton11_2.ButtonPushedFcn = createCallbackFcn(app, @MESButton11_2Pushed, true);
            app.MESButton11_2.Text = 'Doublet';
            app.mtypesentEditField_2Label = uilabel(app.EMG2Panel);
            app.mtypesentEditField_2Label.Text = 'mtype sent';
            app.mtypesentEditField_2 = uieditfield(app.EMG2Panel, 'numeric');
            app.StepLabel_2 = uilabel(app.EMG2Panel);
            app.StepLabel_2.Text = 'Step';
            app.RampLabel_2 = uilabel(app.EMG2Panel);
            app.RampLabel_2.Text = 'Ramp';
            app.TwitchLabel_2 = uilabel(app.EMG2Panel);
            app.TwitchLabel_2.Text = 'Twitch';
            app.BPEMGButton = uibutton(app.EMGTestDataUIFigure, 'push');
            app.BPEMGButton.ButtonPushedFcn = createCallbackFcn(app, @BPEMGButtonPushed, true);
            app.BPEMGButton.Text = 'BP EMG';
            app.TestDataButton = uibutton(app.EMGTestDataUIFigure, 'push');
            app.TestDataButton.ButtonPushedFcn = createCallbackFcn(app, @TestDataButtonPushed, true);
            app.TestDataButton.Text = 'Test Data';
            app.Messages = uitextarea(app.EMGTestDataUIFigure);
            app.ClearMessageButton = uibutton(app.EMGTestDataUIFigure, 'push');
            app.ClearMessageButton.ButtonPushedFcn = createCallbackFcn(app, @ClearMessageButtonPushed, true);
            app.ClearMessageButton.Text = 'clear';
            app.MessagesLabel = uilabel(app.EMGTestDataUIFigure);
            app.MessagesLabel.Text = 'Messages';
            app.ViewEMGButton = uibutton(app.EMGTestDataUIFigure, 'state');
            app.ViewEMGButton.ValueChangedFcn = createCallbackFcn(app, @ViewEMGButtonValueChanged, true);
            app.ViewEMGButton.Text = 'View EMG';
            % Show the figure after all components are created
            app.EMGTestDataUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = emgTestDataGraphTicToc(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EMGTestDataUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EMGTestDataUIFigure)
        end
    end
end