classdef exerciseSetup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        OpenExerciseParametersMenu     matlab.ui.container.Menu
        SaveExerciseParametersMenu     matlab.ui.container.Menu
        SaveAsMenu                     matlab.ui.container.Menu
        PMmenu                         matlab.ui.container.Menu
        SendExertoPMMenu               matlab.ui.container.Menu
        ReadfromPM                     matlab.ui.container.Menu
        SavePMODMenu                   matlab.ui.container.Menu
        ReferenceMenu                  matlab.ui.container.Menu
        PatternNames                   matlab.ui.container.Menu
        UserIDEditField                matlab.ui.control.EditField
        ClearMessageButton             matlab.ui.control.Button
        Messages                       matlab.ui.control.TextArea
        TabGroup                       matlab.ui.container.TabGroup
        DetailedTab                    matlab.ui.container.Tab
        TargetPanel                    matlab.ui.container.Panel
        UpdateButton_2                 matlab.ui.control.Button
        tTotalActualMin                matlab.ui.control.NumericEditField
        ActualTotalminLabel            matlab.ui.control.Label
        tTotalMin                      matlab.ui.control.NumericEditField
        StimminLabel_3                 matlab.ui.control.Label
        tRestMin                       matlab.ui.control.NumericEditField
        StimminLabel_2                 matlab.ui.control.Label
        tStimMin                       matlab.ui.control.NumericEditField
        StimminLabel                   matlab.ui.control.Label
        PresetDropDown                 matlab.ui.control.DropDown
        PresetDropDownLabel            matlab.ui.control.Label
        DetailsSwitch                  matlab.ui.control.Switch
        DetailsSwitchLabel             matlab.ui.control.Label
        ScriptRatemsEditField          matlab.ui.control.NumericEditField
        ScriptRatemsEditFieldLabel     matlab.ui.control.Label
        DescriptionEditField           matlab.ui.control.EditField
        DescriptionEditFieldLabel      matlab.ui.control.Label
        ExerciseNumberDropDown         matlab.ui.control.DropDown
        ExerciseNumberDropDownLabel    matlab.ui.control.Label
        CancelButton                   matlab.ui.control.Button
        ChooseButton                   matlab.ui.control.Button
        ChoosePresetExerciseButton     matlab.ui.control.Button
        DetailsPanel                   matlab.ui.container.Panel
        StartingCommandEditField       matlab.ui.control.NumericEditField
        StartingCommandEditFieldLabel  matlab.ui.control.Label
        RegimensEditField              matlab.ui.control.NumericEditField
        RegimensEditFieldLabel         matlab.ui.control.Label
        RestminEditField               matlab.ui.control.NumericEditField
        RestminEditFieldLabel          matlab.ui.control.Label
        SetsRegimenEditField           matlab.ui.control.NumericEditField
        SetsRegimenEditFieldLabel      matlab.ui.control.Label
        RepsSetEditField               matlab.ui.control.NumericEditField
        RepsSetEditFieldLabel          matlab.ui.control.Label
        OpenButton                     matlab.ui.control.Button
        HoldDownsecEditField           matlab.ui.control.NumericEditField
        RampDownsecEditField           matlab.ui.control.NumericEditField
        HoldUpsecEditField             matlab.ui.control.NumericEditField
        RampUpsecEditField             matlab.ui.control.NumericEditField
        SecRepEditField                matlab.ui.control.NumericEditField
        Image                          matlab.ui.control.Image
        TimeTotalsPanel                matlab.ui.container.Panel
        MinsRestRegEditField           matlab.ui.control.NumericEditField
        RestminLabel                   matlab.ui.control.Label
        minSetEditField                matlab.ui.control.NumericEditField
        SetminLabel                    matlab.ui.control.Label
        TotalhrEditField               matlab.ui.control.NumericEditField
        TotalhrEditFieldLabel          matlab.ui.control.Label
        TotalminEditField              matlab.ui.control.NumericEditField
        TotalminEditFieldLabel         matlab.ui.control.Label
        MinsRegEditField               matlab.ui.control.NumericEditField
        RegminEditFieldLabel           matlab.ui.control.Label
        PatternsPanel                  matlab.ui.container.Panel
        Group2DropDown                 matlab.ui.control.DropDown
        Group2DropDownLabel            matlab.ui.control.Label
        Group1DropDown                 matlab.ui.control.DropDown
        Group1DropDownLabel            matlab.ui.control.Label
        TogetherDropDown               matlab.ui.control.DropDown
        Group2EditField                matlab.ui.control.NumericEditField
        Group2EditFieldLabel           matlab.ui.control.Label
        Group1EditField                matlab.ui.control.NumericEditField
        Group1EditFieldLabel           matlab.ui.control.Label
        LowPowerPanel                  matlab.ui.container.Panel
        LowPowerTable                  matlab.ui.control.Table
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        SimpleTab                      matlab.ui.container.Tab
        CopyfromDetailedButton         matlab.ui.control.Button
        Label_7                        matlab.ui.control.Label
        DescriptionsLabel              matlab.ui.control.Label
        SimpleDescription_5            matlab.ui.control.EditField
        SimpleDescription_4            matlab.ui.control.EditField
        SimpleDescription_3            matlab.ui.control.EditField
        SimpleDescription_2            matlab.ui.control.EditField
        SimpleDescription_1            matlab.ui.control.EditField
        UpdateButton                   matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        SimpleGroupDropDown_5          matlab.ui.control.DropDown
        Label_6                        matlab.ui.control.Label
        SimpleGroupDropDown_4          matlab.ui.control.DropDown
        Label_5                        matlab.ui.control.Label
        ExercisePatternsLabel          matlab.ui.control.Label
        SimpleGroupDropDown_3          matlab.ui.control.DropDown
        Label_4                        matlab.ui.control.Label
        SimpleGroupDropDown_2          matlab.ui.control.DropDown
        Label_3                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        SimpleGroupDropDown_1          matlab.ui.control.DropDown
        Label_2                        matlab.ui.control.Label
        FilenameEditField              matlab.ui.control.EditField
        FilenameEditFieldLabel         matlab.ui.control.Label
        ExerciseParametersSetupLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        timerVal=0; % Description
        nnp=[];
        LowPower=[]; % Description
        nnps=[]; %empty structure
        saveFile=[];
        %         Ex1F57=zeros(1,60); % parameters to be written to 1F57.  Need default values that the exercise routine can handle correctly
        
        ExTable=[];
        ePat=[0,0];
        RateFactor=0.1; %multiply cycles to get seconds
        ExDescription=cell(5,1);  % empty cell array of strings
        firstPlot=0; % update this so that you don't need to adjust the axes each time.
        p1=[]; %plot handle
    end
    
    properties (Access = public)
        callingFigure=[]; %handle to calling figure
        ExNumber=1; %currently displayed Exercise number (1-5)
        TimingXdata=[]; % counting sequence for timing plot
        TimingYdata=[]; % output of timing sequence
        presetArray=zeros(10,11); % each column different preset
        presetDescriptions={[]};
        presetNumPatterns=ones(1,11);
        simpleDefaultPar=zeros(1,50);
        % Code for auto file open
         userFolders=struct;  % userfiles, code, user, config, data, log
         defaultSuffix='_Exercise_Parameters.mat';
         defaultPatternsSuffix='_Patterns.mat'
         defaultName='P00_Exercise_Parameters.mat';
         defaultPatternsName='P00_Patterns.mat';
         defaultFolder=pwd;
    end
    
    methods (Access = public)
        
        function updateDropDowns(app)
            groupsFile = find(cellfun(@(x) ~isempty(x), app.nnps.groups)); %finding non-empty groups
            groupsAll = groupsFile;  % removed union
            %assign names from nnps.groups
            groupNames = cell(length(groupsAll), 1);
            
            for row = 1:length(groupsAll)
                group = groupsAll(row);
                %                 if ismember(group, groupsPatterns)
                unusedStr = [];
                %                 else
                %                     unusedStr = '* ';
                %                 end
                if length(app.nnps.groups)>=group && ~isempty(app.nnps.groups{group})
                    groupNames{row} = [unusedStr num2str(group)  ' - ' app.nnps.groups{group}.name ];
                else
                    groupNames{row} = [unusedStr num2str(group)  ' - ?'];
                end
                
            end
            
            app.Group1DropDown.Items = [{'-'}; groupNames];%skip add/remove
            app.Group1DropDown.ItemsData = [0; groupsAll]; %remove the -1 and leave 0
            app.Group2DropDown.Items = [{'-'};  groupNames];%skip add/remove
            app.Group2DropDown.ItemsData = [ 0; groupsAll]; %remove the -1 and leave 0
            
            app.Group2DropDown.Items = [{'-'};  groupNames];%skip add/remove
            app.Group2DropDown.ItemsData = [ 0; groupsAll]; %remove the -1 and leave 0
            
            app.SimpleGroupDropDown_1.Items = [{'-'};  groupNames];%skip add/remove
            app.SimpleGroupDropDown_1.ItemsData = [ 0; groupsAll]; %remove the -1 and leave 0
            app.SimpleGroupDropDown_2.Items = [{'-'};  groupNames];%skip add/remove
            app.SimpleGroupDropDown_2.ItemsData = [ 0; groupsAll]; %remove the -1 and leave 0
            app.SimpleGroupDropDown_3.Items = [{'-'};  groupNames];%skip add/remove
            app.SimpleGroupDropDown_3.ItemsData = [ 0; groupsAll]; %remove the -1 and leave 0
            app.SimpleGroupDropDown_4.Items = [{'-'};  groupNames];%skip add/remove
            app.SimpleGroupDropDown_4.ItemsData = [ 0; groupsAll]; %remove the -1 and leave 0
            app.SimpleGroupDropDown_5.Items = [{'-'};  groupNames];%skip add/remove
            app.SimpleGroupDropDown_5.ItemsData = [ 0; groupsAll]; %remove the -1 and leave 0    
            
        end
        
        function TopMessage(app,message)
            app.Messages.Value=vertcat(message, string(app.Messages.Value)); % put new message on top
        end
        
        function ClearMessages(app)
            app.Messages.Value=''; % clear messages
        end
        
        function updateDisplay(app)
            %             elapsed=toc(app.timerVal);
            %             fprintf('starting update,  %3.2f sec\n',elapsed);
            %file all the information from the parameter table
            eNum=app.ExNumber;
            app.ePat=app.ExTable.Ex1F57([eNum,eNum+5]);
            ebase=((eNum)*10)+1;
            ePar=app.ExTable{ebase:ebase+9,2};  % need {} to get the values from the table
            %fill in the values
            srate=app.ScriptRatemsEditField.Value/1000;
            % Low power byte
            % Bits 1-7 for nodes 1-6,9 to low power mode.  Ignore bit 8
            % LowPower is a logical table to allow for checkboxes
            app.LowPower{[1:7],1}=logical(bitget(uint8(ePar(1)),[1:7]))';  % need {} because table.  Need ' to get right orientation
            app.LowPowerTable.Data=app.LowPower;
            
            % starting command Bit 1 - (0 simulatneous or 1 alternating), Bit2 -Start(0 or 128)
            alternate=double(bitget(uint8(ePar(2)),1));
            app.TogetherDropDown.Value=num2str(alternate); % 0=together, 1=alternate
            app.StartingCommandEditField.Value=double(bitget(uint8(ePar(2)),2)*128);
            % Update OpenButton here
            if app.StartingCommandEditField.Value==128
               app.OpenButton.Text='128, Middle';
               app.OpenButton.BackgroundColor=[1,.94,.94];
            else
                app.StartingCommandEditField.Value=0;
                app.OpenButton.Text='0, Open';
                app.OpenButton.BackgroundColor=[.94,1,.94];
            end
%
            app.RampUpsecEditField.Value=ePar(3)*app.RateFactor;
            app.HoldUpsecEditField.Value=ePar(4)*app.RateFactor;
            app.RampDownsecEditField.Value=ePar(5)*app.RateFactor;
            app.HoldDownsecEditField.Value=ePar(6)*app.RateFactor;
            app.SecRepEditField.Value=sum(ePar(3:6))*srate; % to get seconds
            app.RepsSetEditField.Value=ePar(7);
            app.SetsRegimenEditField.Value=ePar(8);
            app.minSetEditField.Value=app.SecRepEditField.Value*ePar(7)*(alternate+1)/60;% if alternating, there are twice the cycles
            cyclesperreg=sum(ePar(3:6))*ePar(7)*ePar(8)*(alternate+1);  % if alternating, there are twice the cycles
            app.MinsRegEditField.Value=cyclesperreg*srate/60;
            app.RegimensEditField.Value=ePar(9);
            app.RestminEditField.Value=ePar(10)/4; %15 seconds per index
            app.MinsRestRegEditField.Value=app.RestminEditField.Value;
            if ePar(9)>1 % more than one regimen
                app.TotalminEditField.Value=(app.MinsRegEditField.Value*app.RegimensEditField.Value)+(app.RestminEditField.Value*(ePar(9)-1));
            else
                app.TotalminEditField.Value=(app.MinsRegEditField.Value*app.RegimensEditField.Value);
            end
            app.TotalhrEditField.Value=app.TotalminEditField.Value/60;
            app.DescriptionEditField.Value=app.ExDescription{eNum};
            app.Group1EditField.Value=app.ePat(1);
            app.Group2EditField.Value=app.ePat(2);
            % set the dropdown menu to the value of ePat(1)
            %*** this produces an error if ePat(1) or ePat(2) is greater
            % than the length of the dropdown list(if patterns have not been
            % updated for example.
            ddSize=size(app.Group1DropDown.Items,2);
            if app.ePat(1)>ddSize
                dmessage=['Pattern number exceeded dropdown list.  Check OD directly or load new pattern names'];
                app.TopMessage(dmessage);
                return
            end

            %{
            if app.ePat(1)>ddSize
               ddName=['Pattern ', num2str(app.ePat(1))];
               ddItems=app.Group1DropDown.Items;
               ddData=app.Group1DropDown.ItemsData;
                
               for i=(ddSize+1):app.ePat(1)
                   ddItems{i}=num2str(i);
                   ddData(i)=i;
               end

               ddItems{app.ePat(1)}=ddName;
               app.Group1DropDown.ItemsData=ddData;
               app.Group1DropDown.Items=ddItems;
               dmessage=['added ', ddName, 'to Dropdown list'];
               app.TopMessage(dmessage);
            end
            %}
            if app.ePat(2)>ddSize
                dmessage=['Pattern number exceeded dropdown list.  Check OD directly or load new pattern names'];
                app.TopMessage(dmessage);
                return
            end
            app.Group1DropDown.Value=app.ePat(1);
 %{
            if app.ePat(2)>ddSize
                ddName=['Pattern ', num2str(app.ePat(2))];
               ddItems=app.Group2DropDown.Items;
               ddData=app.Group2DropDown.ItemsData;
                
               for i=(ddSize+1):app.ePat(2)
                   ddItems{i}=num2str(i);
                   ddData(i)=i;
               end

               ddItems{app.ePat(2)}=ddName;
               app.Group2DropDown.ItemsData=ddData;
               app.Group2DropDown.Items=ddItems;
               dmessage=['added ', ddName, 'to Dropdown list'];
               app.TopMessage(dmessage);
            end
 %}
            app.Group2DropDown.Value=app.ePat(2);
                       
            % Next I want to graph the exercise.
            % to do that I need the X and Y interpolation arrays.
            
            xcom=zeros(1,6);
            ycom=zeros(1,6);
            sc=app.StartingCommandEditField.Value;
            ycom(1)=sc;
            ycom(6)=sc;
            ycom(2:5)=[255,255,0,0];
            if sc>0
                scstart=ePar(3)*srate/2;
                scend=scstart;
            else
                scstart=(ePar(3)*srate)-0.1;
                scend=0.1;
            end
            xcom(2)=scstart;
            for i=3:5
                xcom(i)=xcom(i-1)+(ePar(i+1)*srate);
            end
            xcom(6)=xcom(5)+scend;
            % Rather than replotting each time, I should just plot once at startup, then update the x and y data when it
            % changes.
            %             elapsed=toc(app.timerVal);
            %             fprintf('starting plots,  %3.2f sec\n',elapsed);
            % make ui invisible, make all the updates, then display
            %             app.UIAxes.Visible='off';
            app.timingPlot(xcom,ycom);
            
            %             app.UIAxes.Visible='on';
            %             elapsed=toc(app.timerVal);
            %             fprintf('Finished timing plot.  %3.2f sec\n',elapsed);
            app.ExerciseRegimenPlot();
            %             elapsed=toc(app.timerVal);
            %             fprintf('Finished regimen plot.  %3.2f sec\n',elapsed);
        end
        
        function updatePreset(app)
            %run this when a preset is selected
            % keep the same enumber
            eNum=app.ExNumber;
            ebase=((eNum)*10)+1;
            presetNumber=app.PresetDropDown.Value;
            ePar=app.presetArray(:,presetNumber);
            app.DescriptionEditField.Value=app.presetDescriptions{presetNumber};
            app.ExDescription{eNum}=app.presetDescriptions{presetNumber};
            app.ExTable{ebase:ebase+9,2}=ePar;  % need {} to get the values from the table
            app.updateDisplay(); % update display at the end
            if app.presetNumPatterns(presetNumber)==1
                app.Group2EditField.Value=0; %second group not used
                app.Group2DropDown.Value=0; % reset to the top of the list where ItemsData=0
            end
            app.PresetDropDown.Visible='off';
            app.ChooseButton.Visible='off';
            app.CancelButton.Visible='off';
        end
        
        function update1F57table(app)           
            % run this whenever a parameter changes.  Could do each separately, but easier to do all 10 at once
            %  app.ExNumber=app.ExerciseNumberDropDown.Value;
            % use ePar as a temporary variable then copy it over to the array
            eNum=app.ExNumber;
            ebase=((eNum)*10)+1;
            ePar=zeros(10,1);
            
            % Low power byte
            % if the table that changed is app.LowPowerTable, then I need to update app.LowPower first
            LP=app.LogTable2Dec(app.LowPower);
            % starting command Bit 1 - (0 simulatneous or 1 alternating), Bit2 -Start(0 or 128)
            % do them one bit at a time
            bits=1;
            alternate=str2num(app.TogetherDropDown.Value); % 1 if alternate
            %             val=logical(app.alternate);  % skip these because alternate is already 0 or 1
            %             altstart=bitset(0,bits,val,'uint8');
            bits=2;
            val=logical(app.StartingCommandEditField.Value); % 1 if 128
            altstart=bitset(alternate,bits,val,'uint8');
            
            ePar(1)=LP;
            ePar(2)=altstart;
            ePar(3)=app.RampUpsecEditField.Value/app.RateFactor;
            ePar(4)=app.HoldUpsecEditField.Value/app.RateFactor;
            ePar(5)=app.RampDownsecEditField.Value/app.RateFactor;
            ePar(6)=app.HoldDownsecEditField.Value/app.RateFactor;
            ePar(7)=app.RepsSetEditField.Value;
            ePar(8)=app.SetsRegimenEditField.Value;
            ePar(9)=app.RegimensEditField.Value;
            ePar(10)=app.RestminEditField.Value*4; %15 seconds per index
            app.ExTable{ebase:ebase+9,2}=ePar;  % need {} to get the values from the table
            app.updateDisplay(); % update display at the end
        end
        
        function val = LogTable2Dec(~,LPtable)
            val=bin2dec(num2str(flip(LPtable{1:7,1}')));
        end
        
        function ExerciseRegimenPlot(app)
            %alt, settime,sets, regs, rest
            %somewhere in the calculations the xdata has at least one segment that is too narrow
            xdata=[];
            ydata=xdata;
            regs=app.RegimensEditField.Value;
            alt=str2num(app.TogetherDropDown.Value);
            sets=app.SetsRegimenEditField.Value;
            settime=app.minSetEditField.Value; %min not sec
            if alt==1 % alternating
                settime=settime/2;
                %half the time for each set
            end
            rest=app.RestminEditField.Value;
            k=1; %counter for X and y
            xt=0;%xtime
            
            for j=1:regs
                for i=1:sets
                    xdata(k:k+3)=[xt,xt+.01, xt+settime,xt+settime+.01];
                    ydata(k:k+3)=[0,1, 1, 0];
                    k=k+4;
                    xt=xt+settime+.01;
                    
                    
                    if alt==1 % Aternating, twice as many sets
                        xdata(k:k+3)=[xt,xt+.01, xt+settime,xt+settime+.01];
                        ydata(k:k+3)=[0,2, 2, 0];
                        k=k+4;
                        xt=xt+settime+.01;
                    end
                end
                if j<regs
                    %add rest if not at the end
                    xdata(k)=xt;
                    ydata(k)=0;
                    k=k+1;
                    xt=xt+rest; %increment time
                else
                    xdata(k)=xt; %last point
                    ydata(k)=0;
                end
            end
            % % separte set1 and set2
            yp1=ydata;
            yp2=ydata;
            for i=1:size(ydata,2)
                if ydata(i)==1
                    yp1(i)=1;
                    yp2(i)=0;
                end
                %     if alt==1
                if ydata(i)==2
                    yp1(i)=0;
                    yp2(i)=1;
                end
                
            end
            
            
            y2data=[yp1;yp2]';
            %            app.UIAxes2.Visible='off'; % hide while updating
            pex=area(app.UIAxes2,xdata,y2data);
            pex(1).LineWidth=.2;
            pex(2).LineWidth=.2;
            pex(1).FaceColor=[0.59,0.85,0.99];
            pex(2).FaceColor=[0.97,0.77,0.76];
            %maybe these should be in startup
            %             app.UIAxes2.XLabel.String='Time, minutes';
            %             app.UIAxes2.YLabel.String='Pattern On';
            app.UIAxes2.YTick=[];
            app.UIAxes2.YLim=[0 1.1];
            app.UIAxes2.XTickLabelMode='auto';
            app.UIAxes2.XTickMode='auto';
            %            app.UIAxes2.Visible='on'; % show after updating
        end
        
        function loadDefaultExerciseFile(app)
            filename='ExerciseParametersDefault.mat'; % leave path relative, so that it just needs to be on Matlab path
            ld = load(filename);
            fields = {'ExTable','ExDescription'};
            iMissing = find(~isfield(ld, fields));
            
            str = [];
            for i=1:length(iMissing)
                str = [str ', ' fields{iMissing(i)}];
            end
            if ~isempty(str)
                warndlg(['Invalid Exercise Parameter File.  Does not contain variable(s): ' str '.'], 'Open File')
                return;
            end
            % If the file is invalid, we don't want to update anything, including the file name
            app.ExTable = ld.ExTable;
            app.ExDescription=ld.ExDescription;
            app.ExerciseNumberDropDown.Value=1; % dropdown uses strings by default
            app.ExNumber=1; % this is redundant
            app.TopMessage('Default Parameters loaded from file');
            app.updateDisplay();
            
        end
        
        function defaultDropdownPatternNames(app)
            % When opening file, want to fill the dropdowns with something
            names=cell(21,1);
            itemsdata=[0:20]';
            names{1}='-';
            for i=2:21 % randomly pick 20
                names{i}=['Pattern ',num2str(i-1)];
            end
            app.Group1DropDown.Items = names;
            app.Group1DropDown.ItemsData = itemsdata;
            app.Group2DropDown.Items = names;
            app.Group2DropDown.ItemsData = itemsdata;
            
            app.SimpleGroupDropDown_1.Items = names;
            app.SimpleGroupDropDown_1.ItemsData = itemsdata;
            app.SimpleGroupDropDown_2.Items = names;
            app.SimpleGroupDropDown_2.ItemsData = itemsdata;
            app.SimpleGroupDropDown_3.Items = names;
            app.SimpleGroupDropDown_3.ItemsData = itemsdata;
            app.SimpleGroupDropDown_4.Items = names;
            app.SimpleGroupDropDown_4.ItemsData = itemsdata;
            app.SimpleGroupDropDown_5.Items = names;
            app.SimpleGroupDropDown_5.ItemsData = itemsdata; 
        end
        
        function SimpleParameterCalc(app)
            % read app.togetherdropdown to determine whether to divide reps
            divRep=str2num(app.TogetherDropDown.Value)+1; %together is 0, alternating 1
            cycleSec=app.RampUpsecEditField.Value+app.HoldUpsecEditField.Value+app.RampDownsecEditField.Value+app.HoldDownsecEditField.Value;
            % by 2
            % need stimMin to be minutes per regimen, not min/sec
%             app.RepsSetEditField.Value=round(app.tStimMin.Value*60/app.SecRepEditField.Value/divRep,0);% stimSec/secPerRep/divRep
% top=app.tStimMin.Value*60;
% bottom=cycleSec*app.RepsSetEditField.Value*divRep;
% tSetsReg=top/bottom;
             tSetsReg=(app.tStimMin.Value*60)/(cycleSec*app.RepsSetEditField.Value*divRep);
            app.SetsRegimenEditField.Value=ceil(tSetsReg);
%             app.RepsSetEditField.Value=round(app.tStimMin.Value*60/app.SecRepEditField.Value/divRep,0);% stimSec/secPerRep/divRep
            app.RestminEditField.Value=app.tRestMin.Value;
            totalTarget = app.tTotalMin.Value;
            minReg=cycleSec*app.SetsRegimenEditField.Value*app.RepsSetEditField.Value*divRep/60;
            a=minReg;
            b=app.tRestMin.Value;
            % total time = Reg*a + (Reg-1)*b
            % targetreg= (targetmin-b)/(a+b)
            % then round up or down
            % lets say "at least"
            targetReg=(totalTarget-b)/(a+b);
            actualReg=ceil(targetReg);
            app.RegimensEditField.Value=actualReg;
            app.tTotalActualMin.Value=(actualReg*a)+(actualReg-1)*b;
            % I need to update the ePar values before updating the display
            app.update1F57table();
%             pause(.2);
%             app.updateDisplay(); % update display at the end
        end
        
        function OpenFileByName(app,filename,filepath)
            ld = load(fullfile(filepath, filename));
            TempFile = fullfile(filepath, filename);  % save filename as temporary until the file is checked

            fields = {'ExTable','ExDescription'};
            iMissing = find(~isfield(ld, fields));
            
            str = [];
            for i=1:length(iMissing)
                str = [str ', ' fields{iMissing(i)}];
            end
            if ~isempty(str)
                warndlg(['Invalid Exercise Parameter File.  Does not contain variable(s): ' str '.'], 'Open File')
                return;
            end
%             app.timerVal=tic;
%             fprintf('file opened\n')
            % If the file is invalid, we don't want to update anything, including the file name
            app.saveFile = TempFile;
            app.FilenameEditField.Value=app.saveFile;
            app.ExTable = ld.ExTable;
            app.ExDescription=ld.ExDescription;
            app.ExerciseNumberDropDown.Value=1; % dropdown uses strings by default
            app.ExNumber=1; % this is redundant
            app.TopMessage('Parameters loaded from file');
            app.updateDisplay();
            app.populateSimple();
            figure(app.UIFigure); %return focus after nnp opened if needed
        end
    end
    
    methods (Access = private)
        
        
        function newTable = defaultExerciseTable(app)
            %create a cell array, ExParName
            ExParName=cell(60,1);
            % fill the variable with default values
            a={'Ex Low Pwr';'Ex Start Com';'UpRampN';'HoldHighN';'DownRampN';'HoldLowN';'Cycles';'Sets';'Regs';'RestN'};
            b={'Exer7Pat_1';'Exer7Pat_2';'Exer7Pat_3';'Exer7Pat_4';'Exer7Pat_5';'Exer8Pat_1';'Exer8Pat_2';'Exer8Pat_3';'Exer8Pat_4';'Exer8Pat_5'};
            for i=1:10
                ExParName{i}=b{i};
            end
            for n=1:5
                j=n*10;
                for i=1:10
                    ExParName{j+i}=a{i};
                end
            end
            Ex1F57=[1;0;11;2;0;0;0;10;0;0;0;2;20;50;20;50;10;3;10;4;0;0;1;30;20;100;10;1;3;16;0;1;2;50;50;2;1;10;3;16;0;0;20;200;20;100;10;1;3;16;0;0;20;100;20;100;10;1;10;4];
            newTable=table(ExParName,Ex1F57);
        end
        
        
        function timingPlot(app,xcom,ycom)
            if app.firstPlot==0
                app.p1=plot(app.UIAxes,xcom,ycom,'-ro','LineWidth',4);
                app.firstPlot=1; %after first plot, don't need to redraw
                app.UIAxes.YLim=[-10,265];
                app.UIAxes.YTick=[0,128,255];
                app.UIAxes.YTickLabel={'0','128','255'};
            else
                set(app.p1,'xdata',xcom,'ydata',ycom);
            end
            app.UIAxes.XTick=xcom(1,end);
            %             app.UIAxes.Visible='on';
            %             elapsed=toc(app.timerVal);
            %             fprintf('Finished timing plot.  %3.2f sec\n',elapsed);
            
        end
        
        function fillPresetArray(app)
            app.presetArray=[0,0,0,0,0,0,0,0,0,0,0;...
                3,2,2,2,0,2,3,0,1,0,0;...
                30,30,30,30,10,30,30,1,2,20,50;
                50,50,20,80,40,50,50,30,50,200,70;...
                30,30,30,30,30,30,30,20,50,20,50;...
                50,50,80,20,40,50,50,100,2,100,200;...
                5,10,10,10,5,5,5,10,1,10,50;...
                5,5,5,5,1,10,5,1,10,1,1;...
                6,6,6,6,15,6,6,3,3,3,1;...
                8,8,8,8,4,8,8,16,16,16,0];
             app.presetDescriptions={'Hand, two patterns, alternating, balanced';...
                'Hand, one pattern, balanced';...
                'Hand, one pattern, more extension';...
                'Hand, one pattern, more flexion';...
                'Triceps, one pattern, fast extension';...
                'Hand and triceps together';...
                'Hand and triceps,  alternating';...
                'Trunk, lean, one pattern';...
                'Quads, two patterns, alternating';...
                'Glut shift, one pattern';...
                'Trunk, standing, one pattern'};
             app.PresetDropDown.Items=app.presetDescriptions;
             app.PresetDropDown.ItemsData=[1:11];
             app.presetNumPatterns=[2,1,1,1,1,2,2,1,2,1,1];
        end
        
        function fillSimpleDefaults(app)
            oneP=[0,2,30,50,30,50,10,5,7,0];
             app.simpleDefaultPar=[oneP,oneP,oneP,oneP,oneP];
        end
        
        function updateSimpleDropdowns(app,eNum,value)
            switch eNum
                case 1
                    app.SimpleGroupDropDown_1.Value=value;
                case 2
                    app.SimpleGroupDropDown_2.Value=value;
                case 3
                    app.SimpleGroupDropDown_3.Value=value;
                case 4
                    app.SimpleGroupDropDown_4.Value=value;
                case 5
                    app.SimpleGroupDropDown_5.Value=value;
            end
        end
        
        function populateSimple(app)
            % When I read in a file, I need to populate the simple fields
            % Maybe I could make that an option.
            % Read in descriptions
            % Read in the patterns
            app.SimpleDescription_1.Value=app.ExDescription{1};
            app.SimpleDescription_2.Value=app.ExDescription{2};
            app.SimpleDescription_3.Value=app.ExDescription{3};
            app.SimpleDescription_4.Value=app.ExDescription{4};
            app.SimpleDescription_5.Value=app.ExDescription{5};
            app.SimpleGroupDropDown_1.Value=app.ExTable.Ex1F57(1);
            app.SimpleGroupDropDown_2.Value=app.ExTable.Ex1F57(2);
            app.SimpleGroupDropDown_3.Value=app.ExTable.Ex1F57(3);
            app.SimpleGroupDropDown_4.Value=app.ExTable.Ex1F57(4);
            app.SimpleGroupDropDown_5.Value=app.ExTable.Ex1F57(5);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin<2
                NNP=[];
            else
                NNP=varargin{1};
            end
            if nargin>2
                app.callingFigure=varargin{2};
            end
            if nargin>3  % third is userFolders
                app.userFolders=varargin{3};  % userfiles, code, user, config, data, log
                app.UserIDEditField.Value=app.userFolders.code;

            end
            if isempty(NNP)
                app.nnp = NNPAPI();
                figure(app.UIFigure); %return focus after nnp opened if needed
                if isempty(app.nnp.port)
                    %msgbox('No port chosen for NNP Access Point')
                    uialert(app.UIFigure,'No port chosen for NNP Access Point','No NNP AP')
                    delete(app.nnp)
                    %                     return
                end
            else
                app.nnp = NNP;
            end
            app.LowPower=table([false;false;false;false;false;false;false]);
            app.LowPowerTable.Data=app.LowPower;
            app.ExerciseNumberDropDown.ItemsData=1:5;
            app.RateFactor=app.ScriptRatemsEditField.Value/1000;
            % Here I should load the exercise paramters from ExerciseParametersDefault.mat, which will be in the Matlab
            % path.  There are too many rows to just type in here, though I could do that if necessary.
            app.ExDescription={'one';'two';'three';'four';'five'};
            app.defaultDropdownPatternNames(); % fill with default values to make life easier
            app.ExTable=app.defaultExerciseTable();
            app.ExNumber = app.ExerciseNumberDropDown.Value;
            app.UIAxes.Toolbar.Visible='off';
            app.UIAxes2.Toolbar.Visible='off';
            disableDefaultInteractivity(app.UIAxes);
            disableDefaultInteractivity(app.UIAxes2);
%             app.updateDisplay();
            %        app.timerVal=tic;
            app.fillPresetArray();
            app.fillSimpleDefaults();
            if isfield(app.userFolders,'config')&& ~isempty(app.userFolders.config)
                app.defaultFolder=app.userFolders.config;
                filename=[app.userFolders.code,app.defaultSuffix];
                app.defaultName=filename; % this is reduntant, could combine above
                filenamePatterns=[app.userFolders.code,app.defaultPatternsSuffix];
                app.defaultPatternsName=filenamePatterns; % this is reduntant, could combine above
                filepath=app.userFolders.config;
                fullName=fullfile(filepath,filename);
                if exist(fullName,'file')==2  % make sure file exists before trying to open
                    app.OpenFileByName(filename,filepath);
                end
                %load Patterns file
                fullNamePatterns=fullfile(filepath,filenamePatterns);
                if exist(fullNamePatterns,'file')==2  % make sure file exists before trying to open
                    ld = load(fullNamePatterns);
                    %TempFile = fullNamePatterns;  % save filename as temporary until the file is checked
                else
                    return;
                end
                fields = {'patterns', 'channels', 'nodes', 'groups'};
                iMissing = find(~isfield(ld, fields));

                str = [];
                for i=1:length(iMissing)
                    str = [str ', ' fields{iMissing(i)}];
                end
                if ~isempty(str)
                    warndlg(['Invalid NNP System (Pattern Group Names) File.  Does not contain variable(s): ' str '.'], 'Open File')
                    return;
                end
                %        If the file is invalid, we don't want to update anything, including the file name
                %        app.saveFile = TempFile;

                    app.nnps.groups = ld.groups; %for now, this is all I need
                    app.updateDropDowns();
                    
            end
            app.updateDisplay();%
            app.ExerciseNumberDropDownValueChanged();% This includes update display, but sets correct ExNumber first
        end

        % Value changed function: TogetherDropDown
        function TogetherDropDownValueChanged(app, event)
            Value = app.TogetherDropDown.Value;
            switch Value
                case '0'
                    app.TogetherDropDown.BackgroundColor=[.9 1 .9];
                case '1'
                    app.TogetherDropDown.BackgroundColor=[1 .9 .9];
            end
            app.update1F57table();
      
        end

        % Menu selected function: OpenExerciseParametersMenu
        function OpenExerciseParametersMenuSelected(app, event)
           if isfield(app.userFolders,'config')&& ~isempty(app.userFolders.config)
                    fname=[app.userFolders.code,app.defaultSuffix];
                    defName=fullfile(app.userFolders.config,fname); % name with path                  
            else
                defName=app.defaultName; % name without path
            end
			[filename, filepath] = uigetfile('.mat', 'Select Exercise Parameter File',defName); % Exercise parameter file

            if filename
                app.OpenFileByName(filename,filepath);
            end

%{                
            ld = load([filepath filename]);
            TempFile = [filepath filename];  % save filename as temporary until the file is checked
            else
                return;
            end
            fields = {'ExTable','ExDescription'};
            iMissing = find(~isfield(ld, fields));
            
            str = [];
            for i=1:length(iMissing)
                str = [str ', ' fields{iMissing(i)}];
            end
            if ~isempty(str)
                warndlg(['Invalid Exercise Parameter File.  Does not contain variable(s): ' str '.'], 'Open File')
                return;
            end
%             app.timerVal=tic;
%             fprintf('file opened\n')
            % If the file is invalid, we don't want to update anything, including the file name
            app.saveFile = TempFile;
            app.FilenameEditField.Value=app.saveFile;
            app.ExTable = ld.ExTable;
            app.ExDescription=ld.ExDescription;
            app.ExerciseNumberDropDown.Value=1; % dropdown uses strings by default
            app.ExNumber=1; % this is redundant
            app.TopMessage('Parameters loaded from file');
            app.updateDisplay();
            app.populateSimple();
            figure(app.UIFigure); %return focus after nnp opened if needed
%}
        end

        % Value changed function: ExerciseNumberDropDown
        function ExerciseNumberDropDownValueChanged(app, event)
        value = app.ExerciseNumberDropDown.Value;
        app.ExNumber=value;
%         app.timerVal=tic;
%         fprintf('starting Ex %d\n',value);
        app.updateDisplay();
        end

        % Value changed function: ScriptRatemsEditField
        function ScriptRatemsEditFieldValueChanged(app, event)
        value = app.ScriptRatemsEditField.Value;
        app.RateFactor=value/1000;
        end

        % Menu selected function: PatternNames
        function PatternNamesMenuSelected(app, event)
        defaultpath=app.defaultFolder;
        [filename, filepath] = uigetfile('.mat', 'Select Patterns File',defaultpath); %Patterns File
        if filename
            ld = load(fullfile(filepath, filename));
%             TempFile = [filepath filename];  % not planning to save pattern names from here
            
        else
            return;
        end
        fields = {'patterns', 'channels', 'nodes', 'groups'};
        iMissing = find(~isfield(ld, fields));
        
        str = [];
        for i=1:length(iMissing)
            str = [str ', ' fields{iMissing(i)}];
        end
        if ~isempty(str)
            warndlg(['Invalid NNP System (Pattern Group Names) File.  Does not contain variable(s): ' str '.'], 'Open File')
            return;
        end
        %               If the file is invalid, we don't want to update anything, including the file name
%        app.saveFile = TempFile;
        
        app.nnps.groups = ld.groups; %for now, this is all I need
        app.updateDropDowns();
        figure(app.UIFigure); %return focus after nnp opened if needed
        end

        % Menu selected function: SendExertoPMMenu
        function SendExertoPMMenuSelected(app, event)
        result = app.nnp.write(7, '1F57', 39, uint8(app.ExTable.Ex1F57(1:30)'), 'uint8',30);
        if ~isequal(result, 0)
            app.TopMessage('Error writing first 30 byes to PM')
        end
        app.TopMessage('Wrote first 30 byte to PM...');
        pause(.5);
        result = app.nnp.write(7, '1F57', 69, uint8(app.ExTable.Ex1F57(31:60)'), 'uint8',30);
        if ~isequal(result, 0)
            app.TopMessage('Error writing second 30 byes to PM')
        end
        app.TopMessage('Wrote second 30 byte to PM');
        end

        % Menu selected function: ReadfromPM
        function ReadfromPMMenuSelected(app, event)
        rx=app.nnp.read(7,'1F57',39,30,'uint8');
        if ~isempty(rx)
            app.ExTable.Ex1F57(1:30)=double(rx); % all sixty elements
        else
            app.TopMessage('Error reading first 30 byes from PM')
            return % do not update if data not read
        end
        app.TopMessage('Read first 30 bytes of PM 1F57 Exercise parameters');
        pause(.5);
        rx=app.nnp.read(7,'1F57',69,30,'uint8');
        if ~isempty(rx)
            app.ExTable.Ex1F57(31:60)=double(rx); % all sixty elements
        else
            app.TopMessage('Error reading second 30 byes from PM')
            return % do not update if data not read
            
        end
        app.TopMessage('Finished reading PM 1F57 Exercise parameters');
        app.updateDisplay();
        
        end

        % Menu selected function: SaveExerciseParametersMenu
        function SaveExerciseParametersMenuSelected(app, event)
            % Need to save two variables.  Should I create a structure?
            % app.ExTable - need to make sure to copy the parameters correctly from the current displayed set
            % app.ExDescriptions - cell array of strings to display when changing exercise sets
            % assume that the array is properly updated anytime a change is made.
            %check for valid file name.   get new name if not valid
            % save data to file
            if isempty(app.saveFile)
                SaveAsMenuSelected(app, event);
            else
                ExTable=app.ExTable;  % save as local variable to save
                ExDescription=app.ExDescription;  % save as local variable to save
                save(app.saveFile,'ExTable','ExDescription'); % save the whole structure as a variable, rather than individual
                app.TopMessage('Data saved to file');
            end
            figure(app.UIFigure); %return focus after nnp opened if needed
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
            % get a file name and save the data to that file
            % include a default file name.  How about rmParameters.mat
            defName=fullfile(app.userFolders.config,app.defaultName);
            [filename, pathname] = uiputfile('*.mat', 'Save As Exercise Parameters mat File',defName);
            if filename == 0
                figure(app.UIFigure); %return focus after nnp opened if needed
                return
            else
                app.saveFile = fullfile(pathname, filename);
                SaveExerciseParametersMenuSelected(app, event);
                app.FilenameEditField.Value=app.saveFile;
            end
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            if app.StartingCommandEditField.Value==0
                app.StartingCommandEditField.Value=128;
                app.OpenButton.Text='128, Middle';
                app.OpenButton.BackgroundColor=[1,.94,.94];
            else
                app.StartingCommandEditField.Value=0;
                app.OpenButton.Text='0, Open';
                app.OpenButton.BackgroundColor=[.94,1,.94];
            end
            app.update1F57table();
        end

        % Value changed function: RampUpsecEditField
        function RampUpsecEditFieldValueChanged(app, event)
            value = app.RampUpsecEditField.Value;
            app.update1F57table();
        end

        % Value changed function: HoldUpsecEditField
        function HoldUpsecEditFieldValueChanged(app, event)
            value = app.HoldUpsecEditField.Value;
            app.update1F57table();
        end

        % Value changed function: RampDownsecEditField
        function RampDownsecEditFieldValueChanged(app, event)
            value = app.RampDownsecEditField.Value;
            app.update1F57table();
        end

        % Value changed function: HoldDownsecEditField
        function HoldDownsecEditFieldValueChanged(app, event)
            value = app.HoldDownsecEditField.Value;
            app.update1F57table();
        end

        % Value changed function: RepsSetEditField
        function RepsSetEditFieldValueChanged(app, event)
            value = app.RepsSetEditField.Value;
            app.update1F57table();
        end

        % Value changed function: SetsRegimenEditField
        function SetsRegimenEditFieldValueChanged(app, event)
            value = app.SetsRegimenEditField.Value;
            app.update1F57table();
        end

        % Value changed function: RestminEditField
        function RestminEditFieldValueChanged(app, event)
            value = app.RestminEditField.Value;
            app.update1F57table();
        end

        % Value changed function: RegimensEditField
        function RegimensEditFieldValueChanged(app, event)
            value = app.RegimensEditField.Value;
            app.update1F57table();
        end

        % Cell edit callback: LowPowerTable
        function LowPowerTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.LowPower=app.LowPowerTable.Data;
            app.update1F57table();
        end

        % Button pushed function: ClearMessageButton
        function ClearMessageButtonPushed(app, event)
            app.Messages.Value='';
        end

        % Value changed function: Group1DropDown
        function Group1DropDownValueChanged(app, event)
            value = app.Group1DropDown.Value;
            % check whether value is a string or number
           if isnumeric(value)
            app.Group1EditField.Value=value; % if ItemsData is updated, this should work
           end
           app.ePat(1)=value;
           eNum=app.ExNumber;
          app.ExTable.Ex1F57(eNum)=app.ePat(1);
%           switch eNum
%                 case 1
%                     app.SimpleGroupDropDown_1.Value=value;
%                 case 2
%                     app.SimpleGroupDropDown_2.Value=value;
%                 case 3
%                     app.SimpleGroupDropDown_3.Value=value;
%                 case 4
%                     app.SimpleGroupDropDown_4.Value=value;
%                 case 5
%                     app.SimpleGroupDropDown_5.Value=value;
%            end

        end

        % Value changed function: Group2DropDown
        function Group2DropDownValueChanged(app, event)
            value = app.Group2DropDown.Value;
            if isnumeric(value)
                app.Group2EditField.Value=value; % if ItemsData is updated, this should work
            end
          app.ePat(2)=value;
          eNum=app.ExNumber;
          app.ExTable.Ex1F57(eNum+5)=app.ePat(2);
          
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ishandle(app.callingFigure) % check that is is valid (has not been closed
                figure(app.callingFigure); % bring to front
            end
            delete(app)
            
        end

        % Value changed function: DescriptionEditField
        function DescriptionEditFieldValueChanged(app, event)
            value = app.DescriptionEditField.Value;
            eNum=app.ExNumber;
            app.ExDescription{eNum}=value;
        end

        % Value changed function: Group1EditField
        function Group1EditFieldValueChanged(app, event)
          value = app.Group1EditField.Value;
          app.ePat(1)=value;
          eNum=app.ExNumber;
          app.ExTable.Ex1F57(eNum)=app.ePat(1);
          app.Group1DropDown.Value=app.ePat(1);
        end

        % Value changed function: Group2EditField
        function Group2EditFieldValueChanged(app, event)
            value = app.Group2EditField.Value;
            app.ePat(2)=value;
          eNum=app.ExNumber;
          app.ExTable.Ex1F57(eNum+5)=app.ePat(2);
          app.Group2DropDown.Value=app.ePat(2);
        end

        % Button pushed function: ChoosePresetExerciseButton
        function ChoosePresetExerciseButtonPushed(app, event)
            app.PresetDropDown.Visible='on';
            app.ChooseButton.Visible='on';
            app.CancelButton.Visible='on';
        end

        % Button pushed function: ChooseButton
        function ChooseButtonPushed(app, event)
            app.updatePreset();  % only update if this is chosen
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.PresetDropDown.Visible='off';
            app.ChooseButton.Visible='off';
            app.CancelButton.Visible='off';   
        end

        % Value changed function: DetailsSwitch
        function DetailsSwitchValueChanged(app, event)
            value = app.DetailsSwitch.Value;
            if strcmp(value,'Hide')
                app.DetailsPanel.Visible='off';
                 app.LowPowerPanel.Visible='off';
            else
                app.DetailsPanel.Visible='on';
                 app.LowPowerPanel.Visible='on';
            end
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            % change 1F57 to reflect the simple parameter setup
%              app.TopMessage('testpoint');
            app.ExTable.Ex1F57(11:60)=app.simpleDefaultPar;
            app.ExTable.Ex1F57(6:10)=[0, 0, 0, 0, 0];
            app.ExTable.Ex1F57(1)=app.SimpleGroupDropDown_1.Value;
            app.ExTable.Ex1F57(2)=app.SimpleGroupDropDown_2.Value;
            app.ExTable.Ex1F57(3)=app.SimpleGroupDropDown_3.Value;
            app.ExTable.Ex1F57(4)=app.SimpleGroupDropDown_4.Value;
            app.ExTable.Ex1F57(5)=app.SimpleGroupDropDown_5.Value;
            app.ExDescription{1}=app.SimpleDescription_1.Value;
            app.ExDescription{2}=app.SimpleDescription_2.Value;
            app.ExDescription{3}=app.SimpleDescription_3.Value;
            app.ExDescription{4}=app.SimpleDescription_4.Value;
            app.ExDescription{5}=app.SimpleDescription_5.Value;
            % change dropdowns for eNum
            % update pattern list
            %app.ePat
            
            % update displays
            app.updateDisplay();
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
           app.fillSimpleDefaults(); 
           app.SimpleDescription_1.Value='';
           app.SimpleDescription_2.Value='';
           app.SimpleDescription_3.Value='';
           app.SimpleDescription_4.Value='';
           app.SimpleDescription_5.Value='';
           app.SimpleGroupDropDown_1.Value=0;
           app.SimpleGroupDropDown_2.Value=0;
           app.SimpleGroupDropDown_3.Value=0;
           app.SimpleGroupDropDown_4.Value=0;
           app.SimpleGroupDropDown_5.Value=0;
        end

        % Button pushed function: CopyfromDetailedButton
        function CopyfromDetailedButtonPushed(app, event)
            app.populateSimple();
        end

        % Value changed function: tStimMin
        function tStimMinValueChanged(app, event)
%             value = app.tStimMin.Value;
%             app.RepsSetEditField.Value=value*60/app.SecRepEditField.Value;
            app.SimpleParameterCalc();
        end

        % Value changed function: tRestMin
        function tRestMinValueChanged(app, event)
%             value = app.tRestMin.Value;
%             app.RestminEditField.Value=value;
            app.SimpleParameterCalc();
        end

        % Value changed function: tTotalMin
        function tTotalMinValueChanged(app, event)
            app.SimpleParameterCalc();
        end

        % Button pushed function: UpdateButton_2
        function UpdateButton_2Pushed(app, event)
             app.SimpleParameterCalc();
        end

        % Menu selected function: SavePMODMenu
        function SavePMODMenuSelected(app, event)
             if app.nnp.saveOD(7)
                    dmessage = 'PM OD Saved';
            else
                dmessage = 'Failed PM OD Save';
            end
            app.TopMessage(dmessage);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Exercise Setup';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenExerciseParametersMenu = uimenu(app.FileMenu);
            app.OpenExerciseParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenExerciseParametersMenuSelected, true);
            app.OpenExerciseParametersMenu.Text = 'Open Exercise Parameters';
            app.SaveExerciseParametersMenu = uimenu(app.FileMenu);
            app.SaveExerciseParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveExerciseParametersMenuSelected, true);
            app.SaveExerciseParametersMenu.Text = 'Save Exercise Parameters';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Text = 'SaveAs';
            app.PMmenu = uimenu(app.UIFigure);
            app.PMmenu.Text = 'PowerModule';
            app.SendExertoPMMenu = uimenu(app.PMmenu);
            app.SendExertoPMMenu.MenuSelectedFcn = createCallbackFcn(app, @SendExertoPMMenuSelected, true);
            app.SendExertoPMMenu.Text = 'Send Exer to PM';
            app.ReadfromPM = uimenu(app.PMmenu);
            app.ReadfromPM.MenuSelectedFcn = createCallbackFcn(app, @ReadfromPMMenuSelected, true);
            app.ReadfromPM.Text = 'Read Exer from PM';
            app.SavePMODMenu = uimenu(app.PMmenu);
            app.SavePMODMenu.MenuSelectedFcn = createCallbackFcn(app, @SavePMODMenuSelected, true);
            app.SavePMODMenu.Text = 'Save PM OD';
            app.ReferenceMenu = uimenu(app.UIFigure);
            app.ReferenceMenu.Text = 'Reference';
            app.PatternNames = uimenu(app.ReferenceMenu);
            app.PatternNames.MenuSelectedFcn = createCallbackFcn(app, @PatternNamesMenuSelected, true);
            app.PatternNames.Text = 'Pattern Names';
            app.ExerciseParametersSetupLabel = uilabel(app.UIFigure);
            app.ExerciseParametersSetupLabel.Text = 'Exercise Parameters Setup';
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DetailedTab = uitab(app.TabGroup);
            app.DetailedTab.Title = 'Detailed';
            app.UIAxes2 = uiaxes(app.DetailedTab);
            title(app.UIAxes2, 'Regimens')
            xlabel(app.UIAxes2, 'Time, minutes')
            ylabel(app.UIAxes2, 'Pattern(s) On')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes = uiaxes(app.DetailedTab);
            title(app.UIAxes, 'Timing')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Command')
            app.UIAxes.YLim = [0 255];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [0 128 255];
            app.UIAxes.YTickLabel = {'0'; '128'; '255'};
            app.LowPowerPanel = uipanel(app.DetailedTab);
            app.LowPowerPanel.Title = 'Low Power';
            app.LowPowerPanel.Visible = 'off';
            app.LowPowerTable = uitable(app.LowPowerPanel);
            app.LowPowerTable.ColumnName = '';
            app.LowPowerTable.RowName = {'1'; '2'; '3'; '4'; '5'; '6'; '9'};
            app.LowPowerTable.ColumnEditable = true;
            app.LowPowerTable.CellEditCallback = createCallbackFcn(app, @LowPowerTableCellEdit, true);
            app.PatternsPanel = uipanel(app.DetailedTab);
            app.PatternsPanel.Title = 'Patterns';
            app.Group1EditFieldLabel = uilabel(app.PatternsPanel);
            app.Group1EditFieldLabel.Text = 'Group 1';
            app.Group1EditField = uieditfield(app.PatternsPanel, 'numeric');
            app.Group1EditField.ValueChangedFcn = createCallbackFcn(app, @Group1EditFieldValueChanged, true);
            app.Group1EditField.Tooltip = {'First functional group number'};
            app.Group2EditFieldLabel = uilabel(app.PatternsPanel);
            app.Group2EditFieldLabel.Text = 'Group 2';
            app.Group2EditField = uieditfield(app.PatternsPanel, 'numeric');
            app.Group2EditField.ValueChangedFcn = createCallbackFcn(app, @Group2EditFieldValueChanged, true);
            app.Group2EditField.Tooltip = {'Optional second functional group number'};
            app.TogetherDropDown = uidropdown(app.PatternsPanel);
            app.TogetherDropDown.Items = {'Together', 'Alternating'};
            app.TogetherDropDown.ItemsData = {'0', '1'};
            app.TogetherDropDown.ValueChangedFcn = createCallbackFcn(app, @TogetherDropDownValueChanged, true);
            app.TogetherDropDown.Value = '0';
            app.Group1DropDownLabel = uilabel(app.PatternsPanel);
            app.Group1DropDownLabel.Text = 'Group 1';
            app.Group1DropDown = uidropdown(app.PatternsPanel);
            app.Group1DropDown.Items = {};
            app.Group1DropDown.ValueChangedFcn = createCallbackFcn(app, @Group1DropDownValueChanged, true);
            app.Group1DropDown.Tooltip = {'Load patterns from top menu to get actual patterns'};
            app.Group1DropDown.Value = {};
            app.Group2DropDownLabel = uilabel(app.PatternsPanel);
            app.Group2DropDownLabel.Text = 'Group 2';
            app.Group2DropDown = uidropdown(app.PatternsPanel);
            app.Group2DropDown.Items = {};
            app.Group2DropDown.ValueChangedFcn = createCallbackFcn(app, @Group2DropDownValueChanged, true);
            app.Group2DropDown.Tooltip = {'Load patterns from top menu to get actual patterns'};
            app.Group2DropDown.Value = {};
            app.TimeTotalsPanel = uipanel(app.DetailedTab);
            app.TimeTotalsPanel.Title = 'Time Totals';
            app.RegminEditFieldLabel = uilabel(app.TimeTotalsPanel);
            app.RegminEditFieldLabel.Text = 'Reg, min';
            app.MinsRegEditField = uieditfield(app.TimeTotalsPanel, 'numeric');
            app.MinsRegEditField.Editable = 'off';
            app.MinsRegEditField.Value = 1;
            app.TotalminEditFieldLabel = uilabel(app.TimeTotalsPanel);
            app.TotalminEditFieldLabel.Text = 'Total, min';
            app.TotalminEditField = uieditfield(app.TimeTotalsPanel, 'numeric');
            app.TotalminEditField.Editable = 'off';
            app.TotalminEditField.Value = 1;
            app.TotalhrEditFieldLabel = uilabel(app.TimeTotalsPanel);
            app.TotalhrEditFieldLabel.Text = 'Total, hr';
            app.TotalhrEditField = uieditfield(app.TimeTotalsPanel, 'numeric');
            app.TotalhrEditField.Editable = 'off';
            app.TotalhrEditField.Value = 1;
            app.SetminLabel = uilabel(app.TimeTotalsPanel);
            app.SetminLabel.Text = 'Set, min';
            app.minSetEditField = uieditfield(app.TimeTotalsPanel, 'numeric');
            app.minSetEditField.Editable = 'off';
            app.minSetEditField.Value = 1;
            app.RestminLabel = uilabel(app.TimeTotalsPanel);
            app.RestminLabel.Text = 'Rest, min';
            app.MinsRestRegEditField = uieditfield(app.TimeTotalsPanel, 'numeric');
            app.MinsRestRegEditField.Editable = 'off';
            app.MinsRestRegEditField.Value = 1;
            app.DetailsPanel = uipanel(app.DetailedTab);
            app.DetailsPanel.Title = 'Details';
            app.Image = uiimage(app.DetailsPanel);
            app.Image.ImageSource = 'ExersciseSetupImage.png';
            app.SecRepEditField = uieditfield(app.DetailsPanel, 'numeric');
            app.SecRepEditField.Editable = 'off';
            app.SecRepEditField.Value = 36;
            app.RampUpsecEditField = uieditfield(app.DetailsPanel, 'numeric');
            app.RampUpsecEditField.ValueChangedFcn = createCallbackFcn(app, @RampUpsecEditFieldValueChanged, true);
            app.RampUpsecEditField.Value = 3;
            app.HoldUpsecEditField = uieditfield(app.DetailsPanel, 'numeric');
            app.HoldUpsecEditField.ValueChangedFcn = createCallbackFcn(app, @HoldUpsecEditFieldValueChanged, true);
            app.HoldUpsecEditField.Value = 5;
            app.RampDownsecEditField = uieditfield(app.DetailsPanel, 'numeric');
            app.RampDownsecEditField.ValueChangedFcn = createCallbackFcn(app, @RampDownsecEditFieldValueChanged, true);
            app.RampDownsecEditField.Value = 2;
            app.HoldDownsecEditField = uieditfield(app.DetailsPanel, 'numeric');
            app.HoldDownsecEditField.ValueChangedFcn = createCallbackFcn(app, @HoldDownsecEditFieldValueChanged, true);
            app.HoldDownsecEditField.Value = 5;
            app.OpenButton = uibutton(app.DetailsPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = '0, Open';
            app.RepsSetEditFieldLabel = uilabel(app.DetailsPanel);
            app.RepsSetEditFieldLabel.Text = 'Reps/Set';
            app.RepsSetEditField = uieditfield(app.DetailsPanel, 'numeric');
            app.RepsSetEditField.ValueChangedFcn = createCallbackFcn(app, @RepsSetEditFieldValueChanged, true);
            app.RepsSetEditField.Value = 20;
            app.SetsRegimenEditFieldLabel = uilabel(app.DetailsPanel);
            app.SetsRegimenEditFieldLabel.Text = 'Sets/Regimen';
            app.SetsRegimenEditField = uieditfield(app.DetailsPanel, 'numeric');
            app.SetsRegimenEditField.ValueChangedFcn = createCallbackFcn(app, @SetsRegimenEditFieldValueChanged, true);
            app.SetsRegimenEditField.Value = 1;
            app.RestminEditFieldLabel = uilabel(app.DetailsPanel);
            app.RestminEditFieldLabel.Text = 'Rest, min';
            app.RestminEditField = uieditfield(app.DetailsPanel, 'numeric');
            app.RestminEditField.ValueChangedFcn = createCallbackFcn(app, @RestminEditFieldValueChanged, true);
            app.RestminEditField.Value = 5;
            app.RegimensEditFieldLabel = uilabel(app.DetailsPanel);
            app.RegimensEditFieldLabel.Text = 'Regimens';
            app.RegimensEditField = uieditfield(app.DetailsPanel, 'numeric');
            app.RegimensEditField.ValueChangedFcn = createCallbackFcn(app, @RegimensEditFieldValueChanged, true);
            app.RegimensEditField.Value = 1;
            app.StartingCommandEditFieldLabel = uilabel(app.DetailsPanel);
            app.StartingCommandEditFieldLabel.Text = 'Starting Command';
            app.StartingCommandEditField = uieditfield(app.DetailsPanel, 'numeric');
            app.StartingCommandEditField.Limits = [0 255];
            app.StartingCommandEditField.Editable = 'off';
            app.ChoosePresetExerciseButton = uibutton(app.DetailedTab, 'push');
            app.ChoosePresetExerciseButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosePresetExerciseButtonPushed, true);
            app.ChoosePresetExerciseButton.Text = 'Choose Preset Exercise';
            app.ChooseButton = uibutton(app.DetailedTab, 'push');
            app.ChooseButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseButtonPushed, true);
            app.ChooseButton.Visible = 'off';
            app.ChooseButton.Text = 'Choose';
            app.CancelButton = uibutton(app.DetailedTab, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Visible = 'off';
            app.CancelButton.Text = 'Cancel';
            app.ExerciseNumberDropDownLabel = uilabel(app.DetailedTab);
            app.ExerciseNumberDropDownLabel.Text = 'Exercise Number';
            app.ExerciseNumberDropDown = uidropdown(app.DetailedTab);
            app.ExerciseNumberDropDown.Items = {'1', '2', '3', '4', '5'};
            app.ExerciseNumberDropDown.ValueChangedFcn = createCallbackFcn(app, @ExerciseNumberDropDownValueChanged, true);
            app.ExerciseNumberDropDown.Value = '1';
            app.DescriptionEditFieldLabel = uilabel(app.DetailedTab);
            app.DescriptionEditFieldLabel.Text = 'Description';
            app.DescriptionEditField = uieditfield(app.DetailedTab, 'text');
            app.DescriptionEditField.ValueChangedFcn = createCallbackFcn(app, @DescriptionEditFieldValueChanged, true);
            app.ScriptRatemsEditFieldLabel = uilabel(app.DetailedTab);
            app.ScriptRatemsEditFieldLabel.Text = 'ScriptRate, ms';
            app.ScriptRatemsEditField = uieditfield(app.DetailedTab, 'numeric');
            app.ScriptRatemsEditField.ValueChangedFcn = createCallbackFcn(app, @ScriptRatemsEditFieldValueChanged, true);
            app.ScriptRatemsEditField.Value = 100;
            app.DetailsSwitchLabel = uilabel(app.DetailedTab);
            app.DetailsSwitchLabel.Text = 'Details';
            app.DetailsSwitch = uiswitch(app.DetailedTab, 'slider');
            app.DetailsSwitch.Items = {'Hide', 'Show'};
            app.DetailsSwitch.ValueChangedFcn = createCallbackFcn(app, @DetailsSwitchValueChanged, true);
            app.DetailsSwitch.Value = 'Hide';
            app.PresetDropDownLabel = uilabel(app.DetailedTab);
            app.PresetDropDownLabel.Visible = 'off';
            app.PresetDropDownLabel.Text = 'Preset';
            app.PresetDropDown = uidropdown(app.DetailedTab);
            app.PresetDropDown.Visible = 'off';
            app.TargetPanel = uipanel(app.DetailedTab);
            app.TargetPanel.Title = 'Target';
            app.StimminLabel = uilabel(app.TargetPanel);
            app.StimminLabel.Text = 'Stim  min';
            app.tStimMin = uieditfield(app.TargetPanel, 'numeric');
            app.tStimMin.ValueChangedFcn = createCallbackFcn(app, @tStimMinValueChanged, true);
            app.tStimMin.Value = 10;
            app.StimminLabel_2 = uilabel(app.TargetPanel);
            app.StimminLabel_2.Text = 'Rest  min';
            app.tRestMin = uieditfield(app.TargetPanel, 'numeric');
            app.tRestMin.ValueChangedFcn = createCallbackFcn(app, @tRestMinValueChanged, true);
            app.tRestMin.Value = 5;
            app.StimminLabel_3 = uilabel(app.TargetPanel);
            app.StimminLabel_3.Text = 'Total Target min';
            app.tTotalMin = uieditfield(app.TargetPanel, 'numeric');
            app.tTotalMin.ValueChangedFcn = createCallbackFcn(app, @tTotalMinValueChanged, true);
            app.tTotalMin.Value = 90;
            app.ActualTotalminLabel = uilabel(app.TargetPanel);
            app.ActualTotalminLabel.Text = 'Actual Total, min';
            app.tTotalActualMin = uieditfield(app.TargetPanel, 'numeric');
            app.tTotalActualMin.Editable = 'off';
            app.tTotalActualMin.Value = 1;
            app.UpdateButton_2 = uibutton(app.TargetPanel, 'push');
            app.UpdateButton_2.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_2Pushed, true);
            app.UpdateButton_2.Text = 'Update';
            app.SimpleTab = uitab(app.TabGroup);
            app.SimpleTab.Title = 'Simple';
            app.Label_2 = uilabel(app.SimpleTab);
            app.Label_2.Text = '1';
            app.SimpleGroupDropDown_1 = uidropdown(app.SimpleTab);
            app.SimpleGroupDropDown_1.Items = {};
            app.SimpleGroupDropDown_1.Tooltip = {'Load patterns from top menu to get actual patterns'};
            app.SimpleGroupDropDown_1.Value = {};
            app.Label = uilabel(app.SimpleTab);
            app.Label.Text = 'In simple mode, each exercise mode will consist of one pattern which cycles closed and open continuously for 90 minutes or until stopped by the user.  The timing will be the same for each pattern, always starting from the center of the command range.';
            app.Label_3 = uilabel(app.SimpleTab);
            app.Label_3.Text = '2';
            app.SimpleGroupDropDown_2 = uidropdown(app.SimpleTab);
            app.SimpleGroupDropDown_2.Items = {};
            app.SimpleGroupDropDown_2.Tooltip = {'Load patterns from top menu to get actual patterns'};
            app.SimpleGroupDropDown_2.Value = {};
            app.Label_4 = uilabel(app.SimpleTab);
            app.Label_4.Text = '3';
            app.SimpleGroupDropDown_3 = uidropdown(app.SimpleTab);
            app.SimpleGroupDropDown_3.Items = {};
            app.SimpleGroupDropDown_3.Tooltip = {'Load patterns from top menu to get actual patterns'};
            app.SimpleGroupDropDown_3.Value = {};
            app.ExercisePatternsLabel = uilabel(app.SimpleTab);
            app.ExercisePatternsLabel.Text = 'Exercise Patterns';
            app.Label_5 = uilabel(app.SimpleTab);
            app.Label_5.Text = '4';
            app.SimpleGroupDropDown_4 = uidropdown(app.SimpleTab);
            app.SimpleGroupDropDown_4.Items = {};
            app.SimpleGroupDropDown_4.Tooltip = {'Load patterns from top menu to get actual patterns'};
            app.SimpleGroupDropDown_4.Value = {};
            app.Label_6 = uilabel(app.SimpleTab);
            app.Label_6.Text = '5';
            app.SimpleGroupDropDown_5 = uidropdown(app.SimpleTab);
            app.SimpleGroupDropDown_5.Items = {};
            app.SimpleGroupDropDown_5.Tooltip = {'Load patterns from top menu to get actual patterns'};
            app.SimpleGroupDropDown_5.Value = {};
            app.ResetButton = uibutton(app.SimpleTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Tooltip = {'Clear all pattern selections for simple parameters'};
            app.ResetButton.Text = 'Reset';
            app.UpdateButton = uibutton(app.SimpleTab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Tooltip = {'Update detailed parameters to match the simple patterns and parameters'};
            app.UpdateButton.Text = 'Update';
            app.SimpleDescription_1 = uieditfield(app.SimpleTab, 'text');
            app.SimpleDescription_2 = uieditfield(app.SimpleTab, 'text');
            app.SimpleDescription_3 = uieditfield(app.SimpleTab, 'text');
            app.SimpleDescription_4 = uieditfield(app.SimpleTab, 'text');
            app.SimpleDescription_5 = uieditfield(app.SimpleTab, 'text');
            app.DescriptionsLabel = uilabel(app.SimpleTab);
            app.DescriptionsLabel.Text = 'Descriptions';
            app.Label_7 = uilabel(app.SimpleTab);
            app.Label_7.Text = 'Update the parameters before saving or writing to PM if you want to use the simple parameters.';
            app.CopyfromDetailedButton = uibutton(app.SimpleTab, 'push');
            app.CopyfromDetailedButton.ButtonPushedFcn = createCallbackFcn(app, @CopyfromDetailedButtonPushed, true);
            app.CopyfromDetailedButton.Tooltip = {'Copy patterns and descriptions from the detailed version, but keep simple timing parameters'};
            app.CopyfromDetailedButton.Text = {'Copy from'; ' Detailed'};
            app.Messages = uitextarea(app.UIFigure);
            app.ClearMessageButton = uibutton(app.UIFigure, 'push');
            app.ClearMessageButton.ButtonPushedFcn = createCallbackFcn(app, @ClearMessageButtonPushed, true);
            app.ClearMessageButton.Text = 'clear';
            app.UserIDEditField = uieditfield(app.UIFigure, 'text');
            app.UserIDEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exerciseSetup(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end