classdef groupedit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PatternGroupEditorFigure  matlab.ui.Figure
        UITable                   matlab.ui.control.Table
        AddGroupButton            matlab.ui.control.Button
        RemoveGroupButton         matlab.ui.control.Button
        ApplyButton               matlab.ui.control.Button
        RevertButton              matlab.ui.control.Button
    end

    
    properties (Access = public)
        groups = []; % Description
        currentRow = [];
        parentApp = [];
        removePatternsOnApply = [];
        movePatternsOnApply = [];
    end
    
    methods (Access = private)
        
        function populateTable(app)
            k=1;
            d = [];
            for i=1:length(app.groups)
                if ~isempty(app.groups{i})
                    d{k,1} = i;
                    d{k,2} = app.groups{i}.cmdID;
                    d{k,3} = app.groups{i}.name;
                    d{k,4} = app.groups{i}.download;
                    d{k,5} = app.groups{i}.notes;
                    k=k+1;
                end
            end
            app.UITable.Data = d;
        end
        
        function trimGroups(app, group)
            app.groups{group} =[];
            
            % if last group, remove it, not just make it empty
            while group == length( app.groups)
                if isempty(app.groups{group})
                    app.groups(group) = [];
                    group = group - 1;
                    if group == 0
                        break;
                    end
                else 
                    break;
                end
            end
        end
        
        function enableApplyRevert(app, enable)
            app.ApplyButton.Enable = enable;
            app.RevertButton.Enable = enable;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, PARENTAPP)
            app.parentApp = PARENTAPP;
            app.groups = app.parentApp.nnps.groups;
            app.populateTable();
            
            app.UITable.ColumnFormat = {'char', 'char'}; %left align numbers in frist two columns
            app.enableApplyRevert(false);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.currentRow = event.Indices(1);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            row = event.Indices(1);    
            col = event.Indices(2);
            
            group = app.UITable.Data{row, 1};
            
            newData = event.NewData;
            if col ==1 || col == 2
                newData = event.NewData;
                if ischar(newData)
                    newData = str2double(newData);
                end
            end
            switch col
                case 1
                    oldGroup = event.PreviousData;
                    newGroup = newData;
                    activeGroups = cell2mat(app.UITable.Data(:, col));
                    activeGroups(row) = [];
                    if isnan(newGroup) || newGroup>255 || newData<1 || ismember(newGroup, activeGroups)
                           app.UITable.Data{row, col} = oldGroup;
                           warning('group out of range')
                           return
                    end
                    if app.parentApp.groupInUse(oldGroup)
                        if app.parentApp.groupInUse(newGroup)
                            warning('new group in use already')
                            app.UITable.Data{row, col} = oldGroup;
                            return;
                        end
                        
                        resp = questdlg({['Group #' num2str(oldGroup) ' has patterns associated with it.  If moved, the assoicated patterns will also be moved when applying changes.'];'';...
                                    ['Are you sure you want to move all patterns associated with group #' num2str(oldGroup) ' to group #' num2str(newGroup) '?']}, 'Group Editor');
                    
                        %No/Cancel will not move group or patterns associated with group
                        %Yes changes group number and moves associated patterns 
                        if isequal(resp, 'Yes')
                            app.movePatternsOnApply = [app.movePatternsOnApply; [oldGroup newGroup]]; 
                        else
                            app.UITable.Data{row, col} = oldGroup;
                            return
                        end
                    end
                    app.groups{newGroup} = app.groups{oldGroup};
                    app.trimGroups(oldGroup);             
                    app.populateTable();

                case 2
                    if isnan(newData) || newData>8 || newData<1
                       app.UITable.Data{row, col} = event.PreviousData;
                       warning('cmdID out of range')
                       return;
                    else
                        app.groups{group}.cmdID = newData;
                    end
                case 3
                    app.groups{group}.name = newData;
                case 4
                    app.groups{group}.download = newData;
                case 5
                    app.groups{group}.notes = newData;
            end
            % all changes made above should enable the Apply/Revert buttons.
            % any discard changes should have returned before getting here
            app.enableApplyRevert(true);
            
        end

        % Button pushed function: AddGroupButton
        function AddGroupButtonPushed(app, event)

            %find first unused group
            if isempty(app.UITable.Data)
                newGroup = 1;
            else
                activeGroups = cell2mat(app.UITable.Data(:, 1));
                if ismember(1, activeGroups)
                    i = find(diff(activeGroups)>1, 1);
                    if isempty(i)
                        newGroup = activeGroups(end)+1;
                    else
                        newGroup = activeGroups(i)+1;
                    end
                else
                    newGroup = 1;
                end
            end
            
            resp = inputdlg({sprintf('New Group#%2.0f\n\nSpecify the command index to use with this group\ncmdID (1-8):', newGroup), 'Name (optional):'}, 'Group Editor');
            if length(resp)<1
                return;
            end
            cmdID = str2double(resp{1});
            if length(resp)>1
                name = resp{2};
            else 
                name = '?';
            end
            if length(cmdID)~=1 || isnan(cmdID) || cmdID<1 || cmdID>8
                 warning('invalid cmdID entry')
                 return;
             end
            
            %force a column cell array
            if length(app.nnps.groups)==1 && newGroup>1
                app.nnps.groups = [app.nnps.groups; {[]}];
            end 
            app.groups{newGroup} = struct('name', name, 'cmdID', cmdID,'download', true, 'notes', '');
            app.populateTable();
            app.enableApplyRevert(true);
        end

        % Button pushed function: RemoveGroupButton
        function RemoveGroupButtonPushed(app, event)
            if isempty(app.currentRow)  || app.currentRow > size(app.UITable.Data, 1)
                return;
            end
            group = app.UITable.Data{app.currentRow, 1};
            
            if app.parentApp.groupInUse(group)
                resp = questdlg({'This group has patterns associated with it.  If deleted, the assoicated patterns will also be removed when applying changes.';'';...
                    ['Are you sure you want to delete all patterns associated with group #' num2str(group) '?']}, 'Group Editor');
                %No/Cancel will not delete group or patterns associated with group
                %Yes deletes group number and associated patterns 
                if isequal(resp, 'Yes') 
                    app.removePatternsOnApply = [app.removePatternsOnApply; group];
                else
                    return;
                end
            end
            app.trimGroups(group);
            app.populateTable();
            app.enableApplyRevert(true);
        end

        % Close request function: PatternGroupEditorFigure
        function PatternGroupEditorFigureCloseRequest(app, event)
            if isvalid(app.parentApp)
                if ~isequal(app.parentApp.nnps.groups, app.groups)
                    resp = questdlg('Do you want to apply changes?', 'Group Editor');
                    if isequal(resp, 'Yes')
                        app.ApplyButtonPushed([]);
                    elseif ~isequal(resp, 'No')
                        return
                    end
                end
            else
                warning('parent app closed')
            end
            
            delete(app)    
                  
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            if isvalid(app.parentApp)     
                if any(cellfun(@(x) ~isempty(x) && ~ismember(x.cmdID, 1:8),app.groups))
                    warndlg('Cannot apply. At least one group does not have valid cmdID')
                end
                disp('applied groupeditor changes')
                app.parentApp.nnps.groups = app.groups;
                
                for group = app.removePatternsOnApply
                    app.parentApp.removePatternsGroup(group);
                end
                for i = 1:size(app.movePatternsOnApply, 1)
                    oldGroup = app.movePatternsOnApply(i,1);
                    newGroup = app.movePatternsOnApply(i,2);
                    app.parentApp.changePatternsGroup(oldGroup, newGroup)  
                end
                app.removePatternsOnApply = [];
                app.movePatternsOnApply = [];
                app.parentApp.updateDropDowns; 
                app.enableApplyRevert(false);
            else
                warning('parent app closed')
            end    
        end

        % Button pushed function: RevertButton
        function RevertButtonPushed(app, event)
            if isvalid(app.parentApp)
                app.removePatternsOnApply = [];
                app.movePatternsOnApply = [];
                app.groups = app.parentApp.nnps.groups;
                app.populateTable();
                app.enableApplyRevert(false);
            else
                warning('parent app closed')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PatternGroupEditorFigure = uifigure('Visible', 'off');
            app.PatternGroupEditorFigure.Name = 'Pattern Group Editor';
            app.PatternGroupEditorFigure.CloseRequestFcn = createCallbackFcn(app, @PatternGroupEditorFigureCloseRequest, true);
            app.UITable = uitable(app.PatternGroupEditorFigure);
            app.UITable.ColumnName = {'Group'; 'Cmd ID'; 'Name'; 'Download'; 'Notes'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true true true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.AddGroupButton = uibutton(app.PatternGroupEditorFigure, 'push');
            app.AddGroupButton.ButtonPushedFcn = createCallbackFcn(app, @AddGroupButtonPushed, true);
            app.AddGroupButton.Text = '+';
            app.RemoveGroupButton = uibutton(app.PatternGroupEditorFigure, 'push');
            app.RemoveGroupButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveGroupButtonPushed, true);
            app.RemoveGroupButton.Text = '-';
            app.ApplyButton = uibutton(app.PatternGroupEditorFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.RevertButton = uibutton(app.PatternGroupEditorFigure, 'push');
            app.RevertButton.ButtonPushedFcn = createCallbackFcn(app, @RevertButtonPushed, true);
            app.RevertButton.Text = 'Revert';
            % Show the figure after all components are created
            app.PatternGroupEditorFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = groupedit(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PatternGroupEditorFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PatternGroupEditorFigure)
        end
    end
end