classdef keyODParameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        KeyODPar                        matlab.ui.Figure
        NetworkVoltageEditField         matlab.ui.control.NumericEditField
        NetworkVoltageEditFieldLabel    matlab.ui.control.Label
        ScriptEdit                      matlab.ui.control.Button
        SetscriptorderinscriptedtiorLabel  matlab.ui.control.Label
        StartupScriptPointerEditField   matlab.ui.control.NumericEditField
        StartupScriptPointerEditFieldLabel  matlab.ui.control.Label
        ScriptRateEditField             matlab.ui.control.NumericEditField
        ScriptRateEditFieldLabel        matlab.ui.control.Label
        ShutoffVoltageEditField         matlab.ui.control.NumericEditField
        ShutoffVoltageLabel             matlab.ui.control.Label
        SyncPeriodEditField             matlab.ui.control.NumericEditField
        SyncPeriodEditFieldLabel        matlab.ui.control.Label
        ReqChargeCurrentEditField       matlab.ui.control.NumericEditField
        ReqChargeCurrentEditFieldLabel  matlab.ui.control.Label
        SystemControlEditField          matlab.ui.control.NumericEditField
        SystemControlEditFieldLabel     matlab.ui.control.Label
        uns320x190390dec30000Buns80x325010060uns320x3250Label  matlab.ui.control.Label
        KeyODElementstoWriteLabel       matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        PMVersionButton                 matlab.ui.control.Button
        MessagesLabel                   matlab.ui.control.Label
        ReadPM                          matlab.ui.control.Button
        WritePM                         matlab.ui.control.Button
        SavePGButton                    matlab.ui.control.Button
        ClearMessageButton              matlab.ui.control.Button
        Messages                        matlab.ui.control.TextArea
    end

    
    properties (Access = private)
         callingFigure=[]; %handle to calling figure
    end
    
    properties (Access = public)
        nnp=[]; % nnpapi
        filename57=[];
        pathname=[];
        keyOD=struct;
    end
    
    methods (Access = public)
        
      function ClearMessages(app)
           app.Messages.Value=''; % clear messages 
        end
        
        function TopMessage(app,message)
            app.Messages.Value=vertcat(message, string(app.Messages.Value)); % put new message on top
        end
        
        function fillDefault(app)
             app.keyOD.sysControl=400;
             app.keyOD.reqChargeCurrent=50;
             app.keyOD.syncPeriod=60;
             app.keyOD.shutoffVoltage=0;
             app.keyOD.scriptRate=100;
             app.keyOD.startupScriptPointer=7;
             app.keyOD.networkVoltage=8;
              
            % 2001.1  uns32 0x190,  400 dec, sys control
            app.SystemControlEditField.Value=app.keyOD.sysControl;
            
            % 3000.0B uns8,  0x32, 50  req charge current                 
            app.ReqChargeCurrentEditField.Value=app.keyOD.reqChargeCurrent;
            
            % 1006.0, uns32,  0x32, 50 or 0x3C, 60 sync period        
            app.SyncPeriodEditField.Value=app.keyOD.app.keyOD.syncPeriod;
            
            % 3000.E, uns16, 0x00  (maybe higher later) Vbatt shutoff        
            app.ShutoffVoltageEditField.Value=app.keyOD.shutoffVoltage;
            
            % 2001.5, uns32,  0x64, 100 script rate        
            app.ScriptRateEditField.Value=app.keyOD.scriptRate;
            
            % 2001.3, uns8, 0x07, startup script pointer     
            app.StartupScriptPointerEditField.Value=app.keyOD.startupScriptPointer;
    
            % 3010.0, uns8, 0x55, Network Voltage *10        
            app.NetworkVoltageEditField.Value=app.keyOD.networkVoltage/10;
        end
    end
    
    methods (Access = private)
        function ResponseMessage(app,good,message)
            if good
                dmessage=message;
                tbc=[1,1,1];
            else
                dmessage=app.nnp.lastError;
                tbc=[1,1,.7];
            end
            app.MessagesTextArea.Value=dmessage;
            app.MessagesTextArea.BackgroundColor=tbc;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
           if nargin<2
                NNP=[];
            else
                NNP=varargin{1};
            end
            if isempty(NNP)
                app.nnp = NNPAPI();
                if isempty(app.nnp.port)
                    msgbox('No port chosen for NNP Access Point')
                    delete(app.nnp)
                    return
                end
            else
                app.nnp = NNP;
            end
            if nargin>2
                app.callingFigure=varargin{2};
            end   
        end

        % Button pushed function: ReadPM
        function ReadPMButtonPushed(app, event)
    app.ClearMessages();
            % 2001.1  uns32 0x190,  400 dec, sys control
    resp=double(app.nnp.read(7,'2001',1,1,'uint32')); % sys control
    if(length(resp)==1) %valid packet
        app.SystemControlEditField.Value=resp;
        app.TopMessage('read system control');
    else
        app.TopMessage('Err read system control');
    end
    pause(.1);
% 3000.0B uns8,  0x32, 50  req charge current
    resp=double(app.nnp.read(7,'3000',11,1,'uint8')); % req charge current
    if(length(resp)==1) %valid packet
        app.ReqChargeCurrentEditField.Value=resp;
        app.TopMessage('read req current');
    else
        app.TopMessage('Err  req current');
    end
 pause(.1);
% 1006.0, uns32,  0x32, 50 or 0x3C, 60 sync period
    resp=double(app.nnp.read(7,'1006',0,1,'uint32')); % sync period
    if(length(resp)==1) %valid packet
        app.SyncPeriodEditField.Value=resp;
        app.TopMessage('read sync period');
    else
        app.TopMessage('Err sync periodl');
    end 
    pause(.1);
% 3000.E, uns16, 0x00  (maybe higher later) Vbatt shutoff
    resp=double(app.nnp.read(7,'3000',14,1,'uint16')); % Vbatt shutoff
    if(length(resp)==1) %valid packet
        app.ShutoffVoltageEditField.Value=resp;
        app.TopMessage('read Vbatt shutoff');
    else
        app.TopMessage('Err Vbatt shutoff');
    end
     pause(.1);
% 2001.5, uns32,  0x64, 100 script rate
    resp=double(app.nnp.read(7,'2001',5,1,'uint32')); % script rate
    if(length(resp)==1) %valid packet
        app.ScriptRateEditField.Value=resp;
        app.TopMessage('read script rate');
    else
        app.TopMessage('Err script rate');
    end
     pause(.1);
% 2001.3, uns8, 0x07, startup script pointer
    resp=double(app.nnp.read(7,'2001',3,1,'uint8')); % script pointer
    if(length(resp)==1) %valid packet
        app.StartupScriptPointerEditField.Value=resp;
        app.TopMessage('read script pointer');
    else
        app.TopMessage('Err script pointer');
    end
 % 3010.0, uns8, 0x55, Network Voltage *10
     resp=double(app.nnp.read(7,'3010',0,1,'uint8')); % Vnet*10
    if(length(resp)==1) %valid packet
       app.NetworkVoltageEditField.Value=resp/10;
        app.TopMessage('read Vnet');
    else
        app.TopMessage('Err Vnet');
    end
        end

        % Button pushed function: WritePM
        function WritePMButtonPushed(app, event)
        app.ClearMessages();
        % 2001.1  uns32 0x190,  390 dec, sys control
        x=uint32(app.SystemControlEditField.Value);
        resp=app.nnp.write(7, '2001',1,x,'uint32'); % sys control
        if resp==0  %valid packet
            dMessage='write system cl';
        else
            dMessage='ERR system ctl';
        end
        app.TopMessage(dMessage);
        pause(.1);

    % 3000.0B uns8,  0x32, 50  req charge current
        x=uint8(app.ReqChargeCurrentEditField.Value);
        resp=app.nnp.write(7, '3000',11,x,'uint8'); % req charge current
       if resp==0  %valid packet
            dMessage='write rech curr';
        else
            dMessage='ERR rech curr';
        end
        app.TopMessage(dMessage);
        pause(.1);

    % 1006.0, uns32,  0x32, 50 or 0x3C, 60 sync period
         x=uint32(app.SyncPeriodEditField.Value);
        resp=app.nnp.write(7, '1006',0,x,'uint32'); % sync period
       if resp==0  %valid packet
            dMessage='write sync';
        else
            dMessage='ERR sync';
        end
        app.TopMessage(dMessage);
        pause(.1);

    % 3000.E, uns16, 0x00  (maybe higher later) Vbatt shutoff
        x=uint16(app.ShutoffVoltageEditField.Value);
        resp=app.nnp.write(7, '3000',14,x,'uint16'); % Vbatt shutoff
        if resp==0  %valid packet
            dMessage='write V shutoff';
        else
            dMessage='ERR sV shutoff';
        end
        app.TopMessage(dMessage);
        pause(.1);

    % 2001.5, uns32,  0x64, 100 script rate
         x=uint32(app.ScriptRateEditField.Value);
        resp=app.nnp.write(7, '2001',5,x,'uint32'); % script rate
        if resp==0  %valid packet
            dMessage='write script rate';
        else
            dMessage='ERR script rate';
        end
        app.TopMessage(dMessage);
        pause(.1);

    % 2001.3, uns8, 0x07, startup script pointer
         x=uint8(app.StartupScriptPointerEditField.Value);
        resp=app.nnp.write(7, '2001',3,x,'uint8'); % script pointer
        if resp==0  %valid packet
            dMessage='write startup pointer';
        else
            dMessage='ERR startup pointer';
        end
        app.TopMessage(dMessage);
        pause(.1);
    % 3010.0, uns8, 0x55, Network Voltage *10
        x=uint8(app.NetworkVoltageEditField.Value*10); %Vnet*10
        resp=app.nnp.write(7, '3010',0,x,'uint8'); % Vnet
        if resp==0  %valid packet
            app.TopMessage('write Vnet');
        else
            app.TopMessage('Err Vnet');
        end
        pause(.1);


        end

        % Button pushed function: ClearMessageButton
        function ClearMessageButtonPushed(app, event)
          app.Messages.Value=' ';  
        end

        % Button pushed function: PMVersionButton
        function PMVersionButtonPushed(app, event)
            resp=double(app.nnp.read(7,'1018',3,'uint32')); % read revision number to test communications
            %app.ResponseMessage(~isempty(resp),sprintf('PM version %d',resp));
            if ~isempty(resp)
                str=sprintf('PM version %d',resp);
% get temp
             temp=app.nnp.getTemp(7); % PM temperture  
             if ~isempty(temp)
                 str=[str, sprintf('\n PMTemp: %4.1f⁰C',temp)];
             end
% get vbatt
                bat=double(app.nnp.read(7,'3000',13,'uint8')); % read revision number to test communications
                if length(bat)<20

                else
                    batV1 = (bat( 7)+bat( 8)*256)/1000; 
                    batV2 = (bat( 9)+bat(10)*256)/1000; 
                    batV3 = (bat(11)+bat(12)*256)/1000; %in V
   
                    str = [str, sprintf('\nVbatt: %4.2f,  %4.2f,  %4.2f',batV1,batV2,batV3)];
                end
            else
                str='';
            end
            app.TopMessage(str);
        
        end

        % Button pushed function: SavePGButton
        function SavePGButtonPushed(app, event)
            %nmt 0A
            if app.nnp.saveOD(7)
                    dmessage = 'PM OD Saved';
            else
                dmessage = 'Failed PM OD Save';
            end
            app.TopMessage(dmessage);
       
        end

        % Button pushed function: ScriptEdit
        function ScriptEditButtonPushed(app, event)
             scriptedit(app.nnp,app.KeyODPar); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.KeyODPar = uifigure('Visible', 'off');
            app.KeyODPar.Name = 'MATLAB App';
            app.Panel = uipanel(app.KeyODPar);
            app.Panel.Title = 'Panel';
            app.Messages = uitextarea(app.Panel);
            app.ClearMessageButton = uibutton(app.Panel, 'push');
            app.ClearMessageButton.ButtonPushedFcn = createCallbackFcn(app, @ClearMessageButtonPushed, true);
            app.ClearMessageButton.Text = 'clear';
            app.SavePGButton = uibutton(app.Panel, 'push');
            app.SavePGButton.ButtonPushedFcn = createCallbackFcn(app, @SavePGButtonPushed, true);
            app.SavePGButton.Text = {'Save'; 'OD'};
            app.WritePM = uibutton(app.Panel, 'push');
            app.WritePM.ButtonPushedFcn = createCallbackFcn(app, @WritePMButtonPushed, true);
            app.WritePM.Text = {'Write'; 'PM'};
            app.ReadPM = uibutton(app.Panel, 'push');
            app.ReadPM.ButtonPushedFcn = createCallbackFcn(app, @ReadPMButtonPushed, true);
            app.ReadPM.Text = {'Read'; 'PM'};
            app.MessagesLabel = uilabel(app.Panel);
            app.MessagesLabel.Text = 'Messages';
            app.PMVersionButton = uibutton(app.Panel, 'push');
            app.PMVersionButton.ButtonPushedFcn = createCallbackFcn(app, @PMVersionButtonPushed, true);
            app.PMVersionButton.Text = {'PM'; 'Version'};
            app.KeyODElementstoWriteLabel = uilabel(app.KeyODPar);
            app.KeyODElementstoWriteLabel.Text = 'Key OD Elements to Write';
            app.uns320x190390dec30000Buns80x325010060uns320x3250Label = uilabel(app.KeyODPar);
            app.uns320x190390dec30000Buns80x325010060uns320x3250Label.Text = {'2001.1  uns32 0x190,  400 dec, sys control'; '3000.0B uns8,  0x32, 50  req charge current'; '1006.0, uns32,  0x32, 50 or 0x3C, 60 sync'; '3000.E, uns16, 0x00  Vsys*10 shutoff'; '2001.5, uns32,  0x64, 100 script rate'; '2001.3, uns8, 0x07, startup script pointer'; '3010.0 Vnet*10'; ''};
            app.SystemControlEditFieldLabel = uilabel(app.KeyODPar);
            app.SystemControlEditFieldLabel.Text = 'System Control';
            app.SystemControlEditField = uieditfield(app.KeyODPar, 'numeric');
            app.SystemControlEditField.Value = 400;
            app.ReqChargeCurrentEditFieldLabel = uilabel(app.KeyODPar);
            app.ReqChargeCurrentEditFieldLabel.Text = 'Req Charge Current';
            app.ReqChargeCurrentEditField = uieditfield(app.KeyODPar, 'numeric');
            app.ReqChargeCurrentEditField.Value = 50;
            app.SyncPeriodEditFieldLabel = uilabel(app.KeyODPar);
            app.SyncPeriodEditFieldLabel.Text = 'Sync Period';
            app.SyncPeriodEditField = uieditfield(app.KeyODPar, 'numeric');
            app.SyncPeriodEditField.Value = 60;
            app.ShutoffVoltageLabel = uilabel(app.KeyODPar);
            app.ShutoffVoltageLabel.Text = 'Shutoff Voltage';
            app.ShutoffVoltageEditField = uieditfield(app.KeyODPar, 'numeric');
            app.ShutoffVoltageEditField.Value = 31;
            app.ScriptRateEditFieldLabel = uilabel(app.KeyODPar);
            app.ScriptRateEditFieldLabel.Text = 'Script Rate';
            app.ScriptRateEditField = uieditfield(app.KeyODPar, 'numeric');
            app.ScriptRateEditField.Value = 100;
            app.StartupScriptPointerEditFieldLabel = uilabel(app.KeyODPar);
            app.StartupScriptPointerEditFieldLabel.Text = 'Startup Script Pointer';
            app.StartupScriptPointerEditField = uieditfield(app.KeyODPar, 'numeric');
            app.StartupScriptPointerEditField.Value = 7;
            app.SetscriptorderinscriptedtiorLabel = uilabel(app.KeyODPar);
            app.SetscriptorderinscriptedtiorLabel.Text = 'Set script order in script edtior';
            app.ScriptEdit = uibutton(app.KeyODPar, 'push');
            app.ScriptEdit.ButtonPushedFcn = createCallbackFcn(app, @ScriptEditButtonPushed, true);
            app.ScriptEdit.Tooltip = {'Edit, assemble, download and manage scripts on power module'};
            app.ScriptEdit.Text = {'Edit'; 'Scripts'};
            app.NetworkVoltageEditFieldLabel = uilabel(app.KeyODPar);
            app.NetworkVoltageEditFieldLabel.Text = 'Network Voltage';
            app.NetworkVoltageEditField = uieditfield(app.KeyODPar, 'numeric');
            app.NetworkVoltageEditField.Value = 8;
            % Show the figure after all components are created
            app.KeyODPar.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = keyODParameters(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.KeyODPar)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.KeyODPar)
        end
    end
end