classdef lowpower < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LowPowerUIFigure        matlab.ui.Figure
        ButtonLP_1              matlab.ui.control.Button
        SetNodetoLowPowerLabel  matlab.ui.control.Label
        ButtonLP_2              matlab.ui.control.Button
        ButtonLP_3              matlab.ui.control.Button
        ButtonLP_4              matlab.ui.control.Button
        ButtonLP_5              matlab.ui.control.Button
        ButtonLP_6              matlab.ui.control.Button
        ButtonLP_7              matlab.ui.control.Button
        ButtonLP_All            matlab.ui.control.Button
        MessagesLabel           matlab.ui.control.Label
        Messages                matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        nnp=[]; % Description
    end
    
    methods (Access = public)
        
        function SetNodeLP(app,node)
            if app.nnp.enterLowPower(node)
                app.Messages.Value=sprintf('LP node %1.1f\n',node);
            else
                app.Messages.Value=sprintf(['Error ' app.nnp.lastError]);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, NNP)
            if isempty(NNP)
                app.nnp = NNPAPI();
                if isempty(app.nnp.port)
                    msgbox('No port chosen for NNP Access Point')
                    delete(app.nnp)
                    return
                end
            else
                app.nnp = NNP;
            end
            %get network status
            status = app.nnp.getNetworkStatus();
            if isempty(status)
                app.Messages.Value='Cannot read network status';
            else
                if status>0
                    app.Messages.Value='Network is ON';
                else
                    app.Messages.Value='Network is ON';
                end
            end
        
        end

        % Button pushed function: ButtonLP_2
        function ButtonLP_2Pushed(app, event)
            app.SetNodeLP(2);
        end

        % Button pushed function: ButtonLP_1
        function ButtonLP_1Pushed(app, event)
            app.SetNodeLP(1);
        end

        % Button pushed function: ButtonLP_3
        function ButtonLP_3Pushed(app, event)
           app.SetNodeLP(3);
        end

        % Button pushed function: ButtonLP_4
        function ButtonLP_4Pushed(app, event)
            app.SetNodeLP(4);
        end

        % Button pushed function: ButtonLP_5
        function ButtonLP_5Pushed(app, event)
           app.SetNodeLP(5);
            
        end

        % Button pushed function: ButtonLP_6
        function ButtonLP_6Pushed(app, event)
         app.SetNodeLP(6);
        end

        % Button pushed function: ButtonLP_7
        function ButtonLP_7Pushed(app, event)
             app.SetNodeLP(9);
        end

        % Button pushed function: ButtonLP_All
        function ButtonLP_AllPushed(app, event)
            for i=[1,2,3,4,5,6,9]
                app.SetNodeLP(i);
                pause(.1);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LowPowerUIFigure = uifigure('Visible', 'off');
            app.LowPowerUIFigure.Name = 'Low Power';
            app.ButtonLP_1 = uibutton(app.LowPowerUIFigure, 'push');
            app.ButtonLP_1.ButtonPushedFcn = createCallbackFcn(app, @ButtonLP_1Pushed, true);
            app.ButtonLP_1.Text = '1';
            app.SetNodetoLowPowerLabel = uilabel(app.LowPowerUIFigure);
            app.SetNodetoLowPowerLabel.Text = {'Set Node'; 'to Low Power'};
            app.ButtonLP_2 = uibutton(app.LowPowerUIFigure, 'push');
            app.ButtonLP_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonLP_2Pushed, true);
            app.ButtonLP_2.Text = '2';
            app.ButtonLP_3 = uibutton(app.LowPowerUIFigure, 'push');
            app.ButtonLP_3.ButtonPushedFcn = createCallbackFcn(app, @ButtonLP_3Pushed, true);
            app.ButtonLP_3.Text = '3';
            app.ButtonLP_4 = uibutton(app.LowPowerUIFigure, 'push');
            app.ButtonLP_4.ButtonPushedFcn = createCallbackFcn(app, @ButtonLP_4Pushed, true);
            app.ButtonLP_4.Text = '4';
            app.ButtonLP_5 = uibutton(app.LowPowerUIFigure, 'push');
            app.ButtonLP_5.ButtonPushedFcn = createCallbackFcn(app, @ButtonLP_5Pushed, true);
            app.ButtonLP_5.Text = '5';
            app.ButtonLP_6 = uibutton(app.LowPowerUIFigure, 'push');
            app.ButtonLP_6.ButtonPushedFcn = createCallbackFcn(app, @ButtonLP_6Pushed, true);
            app.ButtonLP_6.Text = '6';
            app.ButtonLP_7 = uibutton(app.LowPowerUIFigure, 'push');
            app.ButtonLP_7.ButtonPushedFcn = createCallbackFcn(app, @ButtonLP_7Pushed, true);
            app.ButtonLP_7.Text = '9';
            app.ButtonLP_All = uibutton(app.LowPowerUIFigure, 'push');
            app.ButtonLP_All.ButtonPushedFcn = createCallbackFcn(app, @ButtonLP_AllPushed, true);
            app.ButtonLP_All.Text = 'All';
            app.MessagesLabel = uilabel(app.LowPowerUIFigure);
            app.MessagesLabel.Text = 'Messages';
            app.Messages = uitextarea(app.LowPowerUIFigure);
            % Show the figure after all components are created
            app.LowPowerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lowpower(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LowPowerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LowPowerUIFigure)
        end
    end
end