classdef miniClinicianToolRev1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        miniUIFigure             matlab.ui.Figure
        Image                    matlab.ui.control.Image
        NNPStatusButton          matlab.ui.control.Button
        ParticipantDropDown      matlab.ui.control.DropDown
        DownloadPMDataButton     matlab.ui.control.Button
        StatusPanel              matlab.ui.container.Panel
        SetnewVnetButton         matlab.ui.control.StateButton
        WriteButton              matlab.ui.control.Button
        PMVNETEditField          matlab.ui.control.NumericEditField
        PMTempEditField          matlab.ui.control.NumericEditField
        PMTempEditFieldLabel     matlab.ui.control.Label
        NetworkVEditField        matlab.ui.control.NumericEditField
        NetworkVEditFieldLabel   matlab.ui.control.Label
        BatteryVEditField        matlab.ui.control.NumericEditField
        BatteryVEditFieldLabel   matlab.ui.control.Label
        RefreshButton            matlab.ui.control.Button
        NetworkLamp              matlab.ui.control.Lamp
        NetworkLampLabel         matlab.ui.control.Label
        PMShutdownPanel          matlab.ui.container.Panel
        PMShutdownButton         matlab.ui.control.Button
        AllowShutdownCheckBox    matlab.ui.control.CheckBox
        TabGroup                 matlab.ui.container.TabGroup
        StimEditingTab           matlab.ui.container.Tab
        ClearMessageButton_2     matlab.ui.control.Button
        MessagesTextArea_2       matlab.ui.control.TextArea
        MessagesTextArea_2Label  matlab.ui.control.Label
        ExerciseButton           matlab.ui.control.Button
        ActionPatternMapButton   matlab.ui.control.Button
        ActiveFGButton           matlab.ui.control.Button
        DisableScriptsButton     matlab.ui.control.Button
        DisablescriptsbeforepatterneditingLabel  matlab.ui.control.Label
        PatternEditButton        matlab.ui.control.Button
        ProfilerButton           matlab.ui.control.Button
        ConfigTab                matlab.ui.container.Tab
        ScriptsPanel             matlab.ui.container.Panel
        ScriptsRunningLamp       matlab.ui.control.Lamp
        ScriptsRunningLampLabel  matlab.ui.control.Label
        ScriptStatusButton       matlab.ui.control.Button
        StartupButton            matlab.ui.control.Button
        DisableButton            matlab.ui.control.Button
        EnableButton             matlab.ui.control.Button
        ClearMessageButton_3     matlab.ui.control.Button
        MessagesTextArea_3       matlab.ui.control.TextArea
        MessagesTextArea_3Label  matlab.ui.control.Label
        SystemStatusPanel        matlab.ui.container.Panel
        PMStatusButton           matlab.ui.control.Button
        NetworkScannerButton     matlab.ui.control.Button
        RemoteDiagnosticTab      matlab.ui.container.Tab
        DisplaySwitch            matlab.ui.control.Switch
        RemoteDiagnosticsLabel   matlab.ui.control.Label
        SmartChargeButton        matlab.ui.control.Button
        ODConfigurationPanel     matlab.ui.container.Panel
        KeyPMODButton            matlab.ui.control.Button
        PM1F57Button             matlab.ui.control.Button
        PM1F53Button             matlab.ui.control.Button
        ClearMessageButton       matlab.ui.control.Button
        MessagesTextArea         matlab.ui.control.TextArea
        MessagesTextAreaLabel    matlab.ui.control.Label
        AccessPointPanel         matlab.ui.container.Panel
        RadioSignalLamp          matlab.ui.control.Lamp
        RadioSignalLampLabel     matlab.ui.control.Label
        ConfigAPButton           matlab.ui.control.Button
        NNPAccessPointButton     matlab.ui.control.StateButton
        PowerNetworkPanel        matlab.ui.container.Panel
        EnterWaitingButton       matlab.ui.control.Button
        NetworkONButton          matlab.ui.control.Button
        NetworkOFFButton         matlab.ui.control.Button
    end

    
    % Notes
    % In this clinical suite, I want to add tools that would help
    % therapists or clinical engineers program and edit the stim amd EMG
    % parameters for the NNP
    
    properties (Access = private)
        
    end
    
    properties (Access = public)
        nnp = [];
        enableToolTips = false;
        userFolders=struct;  % userfiles, code, user(path), config, data, log
        mainDir=[];
        oldPath=[];
    end
    
    methods (Access = private)
         
        function enableAll(app, enable)
            %enables all buttons and features on main screen
             app.RefreshButton.Enable = enable;
             app.NetworkLamp.Enable = enable;
             app.NetworkLampLabel.Enable = enable;
             app.BatteryVEditField.Enable = enable;
             app.BatteryVEditFieldLabel.Enable = enable;
             app.PMTempEditField.Enable = enable;
             app.PMTempEditFieldLabel.Enable = enable;
             app.NetworkVEditField.Enable = enable;
             app.NetworkVEditFieldLabel.Enable = enable;
             app.SetnewVnetButton.Enable = enable;
                
             app.NetworkOFFButton.Enable = enable;
             app.NetworkONButton.Enable = enable;
             app.EnterWaitingButton.Enable = enable;

             app.DownloadPMDataButton.Enable = enable;
                
             app.NNPStatusButton.Enable = enable;
             app.ConfigAPButton.Enable = enable;
             app.RadioSignalLamp.Enable = enable;
             app.RadioSignalLampLabel.Enable = enable;

             %enables all Stim tab buttons
             app.ProfilerButton.Enable = enable;
             app.PatternEditButton.Enable = enable;
             app.DisableScriptsButton.Enable = enable;
             app.ExerciseButton.Enable = enable;
             app.ActionPatternMapButton.Enable = enable;
             app.ActiveFGButton.Enable = enable;

             %enables all Config tab buttons
             app.PMStatusButton.Enable = enable;
             app.NetworkScannerButton.Enable = enable;
             app.DisableScriptsButton.Enable = enable;
             app.ScriptsRunningLamp.Enable = enable;
             app.ScriptStatusButton.Enable = enable;
             app.StartupButton.Enable = enable;
             app.DisableButton.Enable = enable;
             app.EnableButton.Enable = enable;

        end
    

        function disableAll(app, disable)
            %disables all buttons and features on main screen
             app.RefreshButton.Enable = disable;
             app.NetworkLamp.Enable = disable;
             app.NetworkLampLabel.Enable = disable;
             app.BatteryVEditField.Enable = disable;
             app.BatteryVEditFieldLabel.Enable = disable;
             app.PMTempEditField.Enable = disable;
             app.PMTempEditFieldLabel.Enable = disable;
             app.NetworkVEditField.Enable = disable;
             app.NetworkVEditFieldLabel.Enable = disable;
             app.SetnewVnetButton.Enable = disable;
                
             app.NetworkOFFButton.Enable = disable;
             app.NetworkONButton.Enable = disable;
             app.EnterWaitingButton.Enable = disable;

             app.DownloadPMDataButton.Enable = disable;
                
             app.NNPStatusButton.Enable = disable;
             app.ConfigAPButton.Enable = disable;
             app.RadioSignalLamp.Enable = disable;
             app.RadioSignalLampLabel.Enable = disable;

              %disables all Stim tab buttons
             app.ProfilerButton.Enable = disable;
             app.PatternEditButton.Enable = disable;
             app.DisableScriptsButton.Enable = disable;
             app.ExerciseButton.Enable = disable;
             app.ActionPatternMapButton.Enable = disable;
             app.ActiveFGButton.Enable = disable;

             %disables all Config tab buttons
             app.PMStatusButton.Enable = disable;
             app.NetworkScannerButton.Enable = disable;
             app.DisableScriptsButton.Enable = disable;
             app.ScriptsRunningLamp.Enable = disable;
             app.ScriptStatusButton.Enable = disable;
             app.StartupButton.Enable = disable;
             app.DisableButton.Enable = disable;
             app.EnableButton.Enable = disable;
        end
        
        %% Message Functions
        
        function ResponseMessage(app,good,message)
            if good
                dmessage=message;
                tbc=[1,1,1];
            else
                dmessage=app.nnp.lastError;
                tbc=[1,1,.7];
            end
            app.MessagesTextArea.Value=dmessage;
            app.MessagesTextArea.BackgroundColor=tbc;
        end
 
        
        function TopMessage(app,message)
             app.MessagesTextArea.Value=vertcat(message, string(app.MessagesTextArea.Value)); % put new message on top
        end
        
        
        function ClearMessages(app)
            app.MessagesTextArea.Value=''; % clear messages
        end

        %% NNP Status 
        function getNNP_Status(app)
            % Checks for status of PM and modes of connected modules
            % plan to use one string for all
             str=''; %initialize string
          
            resp=double(app.nnp.read(7,'1018',3,'uint32')); % read revision number to test communications
            %app.ResponseMessage(~isempty(resp),sprintf('PM version %d',resp));
            if ~isempty(resp)
                pmStr=sprintf('\nPM version %d\n',resp);
                str=[str, pmStr];
            else
                app.MessagesTextArea.Value="Error getting PM version";
            end
            % Next get Status with getStatus

             %  [modes, temp, vsys, net, rssi, lqi, group, lpm] = getStatus(NNP, nodes)
         % Could look only at nodes 1:9 for nodes
             nodes2scan=uint8(1:9);
            [modes, temp, vsys, net, rssi] = app.nnp.getStatus(nodes2scan);
            if ~isempty(modes)
                if net
                    str =  [str,sprintf('Network is ON')];
                    vnet = double(app.nnp.read(7, '3010', 0, 'uint8'))/10; %VNET
                    str =  [str,sprintf('\nNetwork Voltage: %4.1fV',vnet)];
                else
                    str = [str, sprintf('Network is OFF')];
                end
                str = [str, sprintf('\n\nPM Temp: %4.1f⁰C\nRSSI: %4.1fdBm\nVSYS: %4.1fV\nPM mode: %s\n\n', temp, rssi, vsys, modes{7})];
                %insert here a read of battery voltages from 3000.0D
                bat=double(app.nnp.read(7,'3000',13,'uint8')); % read revision number to test communications
                if length(bat)<20

                else
                    bat_V1 = (bat( 7)+bat( 8)*256)/1000; 
                    bat_V2 = (bat( 9)+bat(10)*256)/1000; 
                    bat_V3 = (bat(11)+bat(12)*256)/1000; %in V
   
                    str = [str, sprintf('Vbatt: %4.2f,  %4.2f,  %4.2f\n\n',bat_V1,bat_V2,bat_V3)];
                end
                if net
                    str = [str, sprintf('Note: Nodes not listed on network may be in bootloader mode.\nNodes also may take up to 2s to update.\n\n')];
                    for i=[1:6 8:length(modes)]
                        % here I could look at modes{i} to see if
                        % strcomp='Patient' to determine whether to scan
                        str=[str, sprintf('Node %2d: %s\n', i, modes{i})];
                    end
                else
                    str = [str,'Note: Nodes not listed because network is off'];
                end
        msgbox(str);
            end
        end

        %% Retrieving Radio Signal Strength (RSSI)

        function getRadioSignalStrength(app)

           signal = app.nnp.RSSI;
           double(signal);
           if signal < -75
                app.RadioSignalLamp.Color = [1,0,0]; %red
           elseif (signal <= -72) && (signal > -75)
               app.RadioSignalLamp.Color = [1,1,0]; %yellow
           else
               app.RadioSignalLamp.Color = [0,1,0]; %green
           end

        end

        
    end %end of methods access private
    
    methods (Access = public)
        
        function returnFocus(app)
           figure(app.miniUIFigure); %returns the focus back to the main application figure
        end

        function FillUserDropDown(app)
            % read all the subdirectories in the current folder and fill in
            % the dropdown options
            dd = dir(app.userFolders.userfiles);
            isub = [dd(:).isdir]; %# returns logical vector
            dirList = {dd(isub).name}';
            dirList(ismember(dirList,{'.','..'})) = [];
            none={'none'};
            dirList=[none;dirList]; % add a non selection to force
            % set dropdown values
            app.ParticipantDropDown.Items=dirList;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             app.NNPAccessPointButton.Value = 0;
            [p,~,~]=fileparts(mfilename('fullpath')); % path of the running function
            app.mainDir=p; % path of the running function
            app.userFolders.userfiles=[p,'\UserFiles'];% userfiles, code, user, config, data, log,
            app.FillUserDropDown();
            %app.Commands.SelectedTab=app.Patterns;
        end

        % Value changed function: ParticipantDropDown
        function ParticipantDropDownValueChanged(app, event)
            value = app.ParticipantDropDown.Value;
            if ~strcmp(value,'none')
            userFileDir=app.userFolders.userfiles;
            app.userFolders.code=value;
            app.userFolders.user=fullfile(userFileDir, value); % userfiles, code, user, config, data, log,
            app.userFolders.config=fullfile(app.userFolders.user,'Config');
            app.userFolders.data=fullfile(app.userFolders.user,'Data');
            app.userFolders.log=fullfile(app.userFolders.user,'Log');
            app.ParticipantDropDown.BackgroundColor=[1,1,1];
            app.oldPath=cd( app.userFolders.user); %in case I want an undo
            else
                %set folder to default userfiles path
                app.oldPath=cd(app.userFolders.userfiles);
                app.FillUserDropDown(); %read directory again
            end  
        end

        % Button pushed function: NNPStatusButton
        function NNPStatusButtonPushed(app, event)
             app.getNNP_Status();
             
             % Update Radio Signal Strength Lamp Indicator
             app.getRadioSignalStrength();

        end

        % Value changed function: NNPAccessPointButton
        function NNPAccessPointButtonValueChanged(app, event)
            value = app.NNPAccessPointButton.Value;
            if ~isempty(app.nnp)
                delete(app.nnp)
            end
            
            if app.NNPAccessPointButton.Value
                app.NNPAccessPointButton.Text = 'NNP Access Point-Select Port';
                app.NNPAccessPointButton.BackgroundColor=[.9,.9,.9];
                while true
                    app.TopMessage('Opening...');
                    app.nnp = NNPAPI();
                    app.returnFocus(); 
                    if isempty(app.nnp.port)
                        resp = questdlg('No port chosen for NNP Access Point.  Plug in AccessPoint and try again.  If you do not want to try again, press No and the application will close');
                        if isequal(resp, 'No')
                            %close(app.fillintheblank); % if I want to close something
                            return;
                        end
                    else                        
                        break;
                    end
                end
                %app.nnp.timeout=1;  % set new default timeout that matches 5 retries
                app.nnp.timeout=.5;  % set new default timeout that matches 2 retries (total of 3 tries) at 100ms radio +23ms for WOR
                % how long Matlab waits for usb response - may be some
                % jitter.
                app.NNPAccessPointButton.Text = 'NNP Access Point Open';
                app.returnFocus();    
                app.NNPAccessPointButton.BackgroundColor=[0.81,1.00,0.67];
                
                app.enableAll('on');
                
                app.TopMessage('Finished opening Access Point');
            else
                app.NNPAccessPointButton.Text = 'NNP Access Point Closed';
                app.returnFocus();
                app.NNPAccessPointButton.BackgroundColor=[.96,.96,.96];
                
                app.disableAll('off');

                app.TopMessage('Finished Closing Access Point');
            end
        
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
           %Network Status
           networkStatus = double(app.nnp.read(7, '3004', 1));

           if networkStatus == 1  %network is on
                app.NetworkLamp.Color = 'green';
           else
               app.NetworkLamp.Color = 'red';
           end

           % Update Radio Signal Strength Lamp Indicator
           app.getRadioSignalStrength();

            %Retrieving PM Temperature
            tempRead = app.nnp.read(7, '2003', 1, 'uint16'); 
            if length(tempRead) == 1
                temp = double(tempRead)/10;
            else
                temp = [];
                app.TopMessage('Error Reading the Power Module Temperature');
            end
           
            if ~isempty(temp)
                 app.PMTempEditField.Value = temp;
                 if temp >= 39.0
                     app.PMTempEditField.BackgroundColor = [1.00,0.00,0.00];
                 elseif (temp >= 38.0) && (temp < 39.0)
                     app.PMTempEditField.BackgroundColor = [1.00,1.00,0.00];
                 else
                     app.PMTempEditField.BackgroundColor = [1.00,1.00,1.00];
                 end
            end
             
            %Retrieving Battery Voltage of lowest battery of the 3
            bat=double(app.nnp.read(7,'3000',13,'uint8')); % read revision number to test communications
            
            if length(bat)<20
                batV1 = inf %in Voltage
                batV2 = inf %in Voltage
                batV3 = inf %in Voltage
                % Error Charging Data Packet was not received successfully
            else
                batV1 = (bat( 7)+bat( 8)*256)/1000; %in Voltage
                batV2 = (bat( 9)+bat(10)*256)/1000; %in Voltage
                batV3 = (bat(11)+bat(12)*256)/1000; %in Voltage
            end

            %Find the battery with the lowest voltage
            min_batteryVolt = min([batV1,batV2,batV3]);
            roundedMinBatt = round(min_batteryVolt, 1); %rounds temp up to nearest tenth
            app.BatteryVEditField.Value = roundedMinBatt;

            if roundedMinBatt == 3.2
                 app.PMShutdownButtonPushed()
            elseif (roundedMinBatt > 3.2) && (roundedMinBatt <= 3.3)
                 app.BatteryVEditField.BackgroundColor = [1.00,0.00,0.00];
            elseif (roundedMinBatt > 3.3) && (roundedMinBatt <= 3.5)
                app.BatteryVEditField.BackgroundColor = [1.00,0.00,0.00];
            elseif (roundedMinBatt > 3.5) && (roundedMinBatt <= 3.7)
                app.BatteryVEditField.BackgroundColor = [1.00,1.00,0.00];
            else
                app.BatteryVEditField.BackgroundColor = [1.00,1.00,1.00];
            end

            %Retrieving Network Voltage
            vnet = double(app.nnp.read(7, '3010', 0, 'uint8'))/10; %VNET
            if ~isempty(vnet)
                app.NetworkVEditField.Value = vnet;
            else
                vnet = [];
                app.TopMessage('Error Reading the Network Voltage');
            end
        end

        % Button pushed function: PMShutdownButton
        function PMShutdownButtonPushed(app, event)
            app.nnp.enterWaiting();
            % Add confirmation dialog here
            resp = questdlg('Do you want to power down the Power Module? You will need to use control tower to restart!');
            if isequal(resp, 'Yes')
                response=app.nnp.nmt(7, '9E'); %enable scripts
                if response==hex2dec('9E')
                    dmessage='PM Shutdown';
                    tbc=[1,1,1];
                else
                    dmessage=app.nnp.lastError;
                    tbc=[1,1,.7];
                end
            else
                dmessage='Cancelled';
                tbc=[1,1,1];
            end
            app.MessagesTextArea.Value=dmessage;
            app.MessagesTextArea.BackgroundColor=tbc;
        end

        % Button pushed function: ConfigAPButton
        function ConfigAPButtonPushed(app, event)
            radioconfig(app.nnp,app.miniUIFigure);
        end

        % Value changed function: DisplaySwitch
        function DisplaySwitchValueChanged2(app, event)
            value = app.DisplaySwitch.Value;
            if strcmp(value,'Enable')
              app.SmartChargeButton.Visible='on';
              app.ODConfigurationPanel.Visible='on';
            else
              app.SmartChargeButton.Visible='off'; 
              app.ODConfigurationPanel.Visible='off';
            end

        end

        % Button pushed function: NetworkONButton
        function NetworkONButtonPushed(app, event)
             if app.nnp.enterWaiting()
                if app.nnp.networkOn()
                    dmessage = 'Network On';
                    tbc=[1,1,1];
                    app.RefreshButtonPushed(); %Refresh status box
                else
                    dmessage=app.nnp.lastError;
                    tbc=[1,1,.7];
                end
            else
                dmessage=app.nnp.lastError;
                tbc=[1,1,.7];
            end
            app.MessagesTextArea.Value=dmessage;
            app.MessagesTextArea.BackgroundColor=tbc;
        end

        % Button pushed function: NetworkOFFButton
        function NetworkOFFButtonPushed(app, event)
             if app.nnp.enterWaiting()
                if app.nnp.networkOff()
                    dmessage = 'Network Off';
                     tbc=[1,1,1];
                     app.RefreshButtonPushed(); %Refresh status box
                else
                    dmessage=app.nnp.lastError;
                    tbc=[1,1,.7];
                end
            else
                dmessage=app.nnp.lastError;
                    tbc=[1,1,.7];
            end
            app.MessagesTextArea.Value=dmessage;
            app.MessagesTextArea.BackgroundColor=tbc;
        end

        % Button pushed function: EnterWaitingButton
        function EnterWaitingButtonPushed(app, event)
            if ~app.nnp.enterWaiting() %all nodes
                    dMessage='Error Enter Waiting';
                else
                    dMessage='Enter Waiting';
                end
                 app.TopMessage(dMessage);
        end

        % Button pushed function: DownloadPMDataButton
        function DownloadPMDataButtonPushed(app, event)
             pmReadRestoreLog(app.nnp,app.miniUIFigure,app.userFolders);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.miniUIFigure = uifigure('Visible', 'off');
            app.miniUIFigure.Name = 'MATLAB App';
            app.PowerNetworkPanel = uipanel(app.miniUIFigure);
            app.PowerNetworkPanel.Title = 'Power Network';
            app.NetworkOFFButton = uibutton(app.PowerNetworkPanel, 'push');
            app.NetworkOFFButton.ButtonPushedFcn = createCallbackFcn(app, @NetworkOFFButtonPushed, true);
            app.NetworkOFFButton.Enable = 'off';
            app.NetworkOFFButton.Text = {'Network '; 'OFF'};
            app.NetworkONButton = uibutton(app.PowerNetworkPanel, 'push');
            app.NetworkONButton.ButtonPushedFcn = createCallbackFcn(app, @NetworkONButtonPushed, true);
            app.NetworkONButton.Enable = 'off';
            app.NetworkONButton.Tooltip = {'Turn network on and set put in app mode'};
            app.NetworkONButton.Text = {'Network '; 'ON'};
            app.EnterWaitingButton = uibutton(app.PowerNetworkPanel, 'push');
            app.EnterWaitingButton.ButtonPushedFcn = createCallbackFcn(app, @EnterWaitingButtonPushed, true);
            app.EnterWaitingButton.Enable = 'off';
            app.EnterWaitingButton.Text = 'Enter Waiting';
            app.AccessPointPanel = uipanel(app.miniUIFigure);
            app.AccessPointPanel.Title = 'Access Point';
            app.NNPAccessPointButton = uibutton(app.AccessPointPanel, 'state');
            app.NNPAccessPointButton.ValueChangedFcn = createCallbackFcn(app, @NNPAccessPointButtonValueChanged, true);
            app.NNPAccessPointButton.Tooltip = {'Enable or disable access point'};
            app.NNPAccessPointButton.Text = 'NNP Access Point Open';
            app.ConfigAPButton = uibutton(app.AccessPointPanel, 'push');
            app.ConfigAPButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigAPButtonPushed, true);
            app.ConfigAPButton.Enable = 'off';
            app.ConfigAPButton.Tooltip = {'Configure access point radio settings'};
            app.ConfigAPButton.Text = 'Radio';
            app.RadioSignalLampLabel = uilabel(app.AccessPointPanel);
            app.RadioSignalLampLabel.Enable = 'off';
            app.RadioSignalLampLabel.Text = 'Radio Signal';
            app.RadioSignalLamp = uilamp(app.AccessPointPanel);
            app.RadioSignalLamp.Enable = 'off';
            app.MessagesTextAreaLabel = uilabel(app.miniUIFigure);
            app.MessagesTextAreaLabel.Text = 'Messages';
            app.MessagesTextArea = uitextarea(app.miniUIFigure);
            app.ClearMessageButton = uibutton(app.miniUIFigure, 'push');
            app.ClearMessageButton.Text = 'clear';
            app.TabGroup = uitabgroup(app.miniUIFigure);
            app.StimEditingTab = uitab(app.TabGroup);
            app.StimEditingTab.Title = 'Stim Editing';
            app.ProfilerButton = uibutton(app.StimEditingTab, 'push');
            app.ProfilerButton.Enable = 'off';
            app.ProfilerButton.Tooltip = {'Profile electrodes'};
            app.ProfilerButton.Text = 'Profiler';
            app.PatternEditButton = uibutton(app.StimEditingTab, 'push');
            app.PatternEditButton.Enable = 'off';
            app.PatternEditButton.Tooltip = {'Edit patterns and download to remote modules'};
            app.PatternEditButton.Text = {'Pattern'; 'Edit'};
            app.DisablescriptsbeforepatterneditingLabel = uilabel(app.StimEditingTab);
            app.DisablescriptsbeforepatterneditingLabel.Text = '!! Disable scripts before pattern editing';
            app.DisableScriptsButton = uibutton(app.StimEditingTab, 'push');
            app.DisableScriptsButton.Tooltip = {'Disable scripts in the ower module'};
            app.DisableScriptsButton.Text = {'Disable '; 'PM Scripts'};
            app.ActiveFGButton = uibutton(app.StimEditingTab, 'push');
            app.ActiveFGButton.Enable = 'off';
            app.ActiveFGButton.Text = 'Active FG';
            app.ActionPatternMapButton = uibutton(app.StimEditingTab, 'push');
            app.ActionPatternMapButton.Enable = 'off';
            app.ActionPatternMapButton.Tooltip = {'Set exercise parameters'};
            app.ActionPatternMapButton.Text = {'Action to FG '; 'Pattern Map'};
            app.ExerciseButton = uibutton(app.StimEditingTab, 'push');
            app.ExerciseButton.Enable = 'off';
            app.ExerciseButton.Tooltip = {'Set exercise parameters'};
            app.ExerciseButton.Text = 'Exercise';
            app.MessagesTextArea_2Label = uilabel(app.StimEditingTab);
            app.MessagesTextArea_2Label.Text = 'Messages';
            app.MessagesTextArea_2 = uitextarea(app.StimEditingTab);
            app.ClearMessageButton_2 = uibutton(app.StimEditingTab, 'push');
            app.ClearMessageButton_2.Text = 'clear';
            app.ConfigTab = uitab(app.TabGroup);
            app.ConfigTab.Title = 'Config';
            app.SystemStatusPanel = uipanel(app.ConfigTab);
            app.SystemStatusPanel.Title = 'System Status';
            app.NetworkScannerButton = uibutton(app.SystemStatusPanel, 'push');
            app.NetworkScannerButton.Tooltip = {'Scan the remote modules located on network under various conditions'};
            app.NetworkScannerButton.Text = {'Network'; 'Scanner'};
            app.PMStatusButton = uibutton(app.SystemStatusPanel, 'push');
            app.PMStatusButton.Tooltip = {'Read PM version as test of communications'};
            app.PMStatusButton.Text = {'PM'; 'Status'};
            app.MessagesTextArea_3Label = uilabel(app.ConfigTab);
            app.MessagesTextArea_3Label.Text = 'Messages';
            app.MessagesTextArea_3 = uitextarea(app.ConfigTab);
            app.ClearMessageButton_3 = uibutton(app.ConfigTab, 'push');
            app.ClearMessageButton_3.Text = 'clear';
            app.ScriptsPanel = uipanel(app.ConfigTab);
            app.ScriptsPanel.Title = 'Scripts';
            app.EnableButton = uibutton(app.ScriptsPanel, 'push');
            app.EnableButton.Text = 'Enable';
            app.DisableButton = uibutton(app.ScriptsPanel, 'push');
            app.DisableButton.Text = 'Disable';
            app.StartupButton = uibutton(app.ScriptsPanel, 'push');
            app.StartupButton.Text = 'Startup';
            app.ScriptStatusButton = uibutton(app.ScriptsPanel, 'push');
            app.ScriptStatusButton.Text = 'Status';
            app.ScriptsRunningLampLabel = uilabel(app.ScriptsPanel);
            app.ScriptsRunningLampLabel.Text = 'Scripts Running';
            app.ScriptsRunningLamp = uilamp(app.ScriptsPanel);
            app.RemoteDiagnosticTab = uitab(app.TabGroup);
            app.RemoteDiagnosticTab.Title = 'Remote Diagnostic';
            app.ODConfigurationPanel = uipanel(app.RemoteDiagnosticTab);
            app.ODConfigurationPanel.Title = 'OD Configuration';
            app.ODConfigurationPanel.Visible = 'off';
            app.PM1F53Button = uibutton(app.ODConfigurationPanel, 'push');
            app.PM1F53Button.Text = 'PM 1F53';
            app.PM1F57Button = uibutton(app.ODConfigurationPanel, 'push');
            app.PM1F57Button.Text = 'PM 1F57';
            app.KeyPMODButton = uibutton(app.ODConfigurationPanel, 'push');
            app.KeyPMODButton.Text = 'Key PM OD';
            app.SmartChargeButton = uibutton(app.RemoteDiagnosticTab, 'push');
            app.SmartChargeButton.Visible = 'off';
            app.SmartChargeButton.Text = {'Smart'; 'Charge'};
            app.RemoteDiagnosticsLabel = uilabel(app.RemoteDiagnosticTab);
            app.RemoteDiagnosticsLabel.Text = 'Remote Diagnostics';
            app.DisplaySwitch = uiswitch(app.RemoteDiagnosticTab, 'slider');
            app.DisplaySwitch.Items = {'Disable', 'Enable'};
            app.DisplaySwitch.ValueChangedFcn = createCallbackFcn(app, @DisplaySwitchValueChanged2, true);
            app.DisplaySwitch.Value = 'Enable';
            app.PMShutdownPanel = uipanel(app.miniUIFigure);
            app.PMShutdownPanel.Title = 'PM Shutdown';
            app.AllowShutdownCheckBox = uicheckbox(app.PMShutdownPanel);
            app.AllowShutdownCheckBox.Text = 'Allow Shutdown';
            app.PMShutdownButton = uibutton(app.PMShutdownPanel, 'push');
            app.PMShutdownButton.ButtonPushedFcn = createCallbackFcn(app, @PMShutdownButtonPushed, true);
            app.PMShutdownButton.Visible = 'off';
            app.PMShutdownButton.Tooltip = {'Profile electrodes'};
            app.PMShutdownButton.Text = 'PM Shutdown';
            app.StatusPanel = uipanel(app.miniUIFigure);
            app.StatusPanel.Title = 'Status';
            app.NetworkLampLabel = uilabel(app.StatusPanel);
            app.NetworkLampLabel.Enable = 'off';
            app.NetworkLampLabel.Text = 'Network';
            app.NetworkLamp = uilamp(app.StatusPanel);
            app.NetworkLamp.Enable = 'off';
            app.RefreshButton = uibutton(app.StatusPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Enable = 'off';
            app.RefreshButton.Text = 'Refresh';
            app.BatteryVEditFieldLabel = uilabel(app.StatusPanel);
            app.BatteryVEditFieldLabel.Enable = 'off';
            app.BatteryVEditFieldLabel.Text = 'Battery V';
            app.BatteryVEditField = uieditfield(app.StatusPanel, 'numeric');
            app.BatteryVEditField.Enable = 'off';
            app.NetworkVEditFieldLabel = uilabel(app.StatusPanel);
            app.NetworkVEditFieldLabel.Enable = 'off';
            app.NetworkVEditFieldLabel.Text = 'Network V';
            app.NetworkVEditField = uieditfield(app.StatusPanel, 'numeric');
            app.NetworkVEditField.Enable = 'off';
            app.PMTempEditFieldLabel = uilabel(app.StatusPanel);
            app.PMTempEditFieldLabel.Enable = 'off';
            app.PMTempEditFieldLabel.Text = 'PM Temp';
            app.PMTempEditField = uieditfield(app.StatusPanel, 'numeric');
            app.PMTempEditField.Enable = 'off';
            app.PMVNETEditField = uieditfield(app.StatusPanel, 'numeric');
            app.PMVNETEditField.Enable = 'off';
            app.PMVNETEditField.Value = 8.5;
            app.WriteButton = uibutton(app.StatusPanel, 'push');
            app.WriteButton.Enable = 'off';
            app.WriteButton.Text = 'Write';
            app.SetnewVnetButton = uibutton(app.StatusPanel, 'state');
            app.SetnewVnetButton.Enable = 'off';
            app.SetnewVnetButton.Text = 'Set new Vnet';
            app.DownloadPMDataButton = uibutton(app.miniUIFigure, 'push');
            app.DownloadPMDataButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadPMDataButtonPushed, true);
            app.DownloadPMDataButton.Enable = 'off';
            app.DownloadPMDataButton.Tooltip = {'Read the participant data log file from the PM memory'};
            app.DownloadPMDataButton.Text = {'Download '; 'PM Data'; ''};
            app.ParticipantDropDown = uidropdown(app.miniUIFigure);
            app.ParticipantDropDown.Items = {'Choose User...'};
            app.ParticipantDropDown.ValueChangedFcn = createCallbackFcn(app, @ParticipantDropDownValueChanged, true);
            app.ParticipantDropDown.Value = 'Choose User...';
            app.NNPStatusButton = uibutton(app.miniUIFigure, 'push');
            app.NNPStatusButton.ButtonPushedFcn = createCallbackFcn(app, @NNPStatusButtonPushed, true);
            app.NNPStatusButton.Enable = 'off';
            app.NNPStatusButton.Tooltip = {''};
            app.NNPStatusButton.Text = 'NNP Status';
            app.Image = uiimage(app.miniUIFigure);
            app.Image.ImageSource = 'DocumentsFolder.png';
            % Show the figure after all components are created
            app.miniUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = miniClinicianToolRev1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.miniUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.miniUIFigure)
        end
    end
end