classdef netscannerg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NetworkScannerUIFigure    matlab.ui.Figure
        ScriptsMenu               matlab.ui.container.Menu
        DisableMenu               matlab.ui.container.Menu
        EnableMenu                matlab.ui.container.Menu
        StartupMenu               matlab.ui.container.Menu
        AccMenu                   matlab.ui.container.Menu
        DisableAccPDOsMenu        matlab.ui.container.Menu
        ResetPMErrCnt_Button      matlab.ui.control.Button
        highlightDropDown         matlab.ui.control.DropDown
        highlightDropDownLabel    matlab.ui.control.Label
        ResetVinPlotButton        matlab.ui.control.Button
        PlotVinCheckBox           matlab.ui.control.CheckBox
        TestFeaturesButton        matlab.ui.control.Button
        EnterPatientButton        matlab.ui.control.Button
        NetStatusEditField        matlab.ui.control.NumericEditField
        PMModeTextEditField       matlab.ui.control.EditField
        PMModeEditField           matlab.ui.control.NumericEditField
        PMModeEditFieldLabel      matlab.ui.control.Label
        WriteButton               matlab.ui.control.Button
        CommentsTextArea          matlab.ui.control.TextArea
        CommentsTextAreaLabel     matlab.ui.control.Label
        CommentFileNameLabel      matlab.ui.control.Label
        CommentFileNameEditField  matlab.ui.control.EditField
        Label                     matlab.ui.control.Label
        LowPower                  matlab.ui.control.Button
        FcloseButton              matlab.ui.control.Button
        NewLogFile                matlab.ui.control.Button
        LogtofileButton           matlab.ui.control.StateButton
        UserEditField             matlab.ui.control.NumericEditField
        UserEditFieldLabel        matlab.ui.control.Label
        LogFileNameLabel          matlab.ui.control.Label
        FolderEditField           matlab.ui.control.EditField
        FolderButton              matlab.ui.control.Button
        LogFilenameEditField      matlab.ui.control.EditField
        APRSSIEditField           matlab.ui.control.NumericEditField
        APRSSIEditFieldLabel      matlab.ui.control.Label
        GetStatusButton           matlab.ui.control.Button
        NetPanel                  matlab.ui.container.Panel
        AttemptEditField_10       matlab.ui.control.NumericEditField
        AttemptEditField_9        matlab.ui.control.NumericEditField
        AttemptEditField_8        matlab.ui.control.NumericEditField
        AttemptEditField_7        matlab.ui.control.NumericEditField
        AttemptEditField_6        matlab.ui.control.NumericEditField
        AttemptEditField_5        matlab.ui.control.NumericEditField
        AttemptEditField_4        matlab.ui.control.NumericEditField
        AttemptEditField_3        matlab.ui.control.NumericEditField
        AttemptEditField_2        matlab.ui.control.NumericEditField
        AttemptEditField_1        matlab.ui.control.NumericEditField
        TempEditField_10          matlab.ui.control.NumericEditField
        TempEditField_9           matlab.ui.control.NumericEditField
        TempEditField_8           matlab.ui.control.NumericEditField
        TempEditField_7           matlab.ui.control.NumericEditField
        TempEditField_6           matlab.ui.control.NumericEditField
        TempEditField_5           matlab.ui.control.NumericEditField
        TempEditField_4           matlab.ui.control.NumericEditField
        TempEditField_3           matlab.ui.control.NumericEditField
        TempEditField_2           matlab.ui.control.NumericEditField
        TempCheckBox              matlab.ui.control.CheckBox
        TempEditField_1           matlab.ui.control.NumericEditField
        ErrorMessageLabel         matlab.ui.control.Label
        LastErrEditField_10       matlab.ui.control.EditField
        LastErrEditField_9        matlab.ui.control.EditField
        LastErrEditField_8        matlab.ui.control.EditField
        LastErrEditField_7        matlab.ui.control.EditField
        LastErrEditField_6        matlab.ui.control.EditField
        LastErrEditField_5        matlab.ui.control.EditField
        LastErrEditField_4        matlab.ui.control.EditField
        LastErrEditField_3        matlab.ui.control.EditField
        LastErrEditField_2        matlab.ui.control.EditField
        LastErrEditField_1        matlab.ui.control.EditField
        CANErrCheckBox            matlab.ui.control.CheckBox
        VINVCheckBox              matlab.ui.control.CheckBox
        ExternalErrorsLabel       matlab.ui.control.Label
        NetworkErrorsLabel        matlab.ui.control.Label
        SWRevLabel                matlab.ui.control.Label
        ResetCountersButton       matlab.ui.control.Button
        VINEditField_10           matlab.ui.control.NumericEditField
        CANErrEditField_10        matlab.ui.control.NumericEditField
        VINEditField_9            matlab.ui.control.NumericEditField
        CANErrEditField_9         matlab.ui.control.NumericEditField
        VINEditField_8            matlab.ui.control.NumericEditField
        CANErrEditField_8         matlab.ui.control.NumericEditField
        VINEditField_7            matlab.ui.control.NumericEditField
        CANErrEditField_7         matlab.ui.control.NumericEditField
        VINEditField_6            matlab.ui.control.NumericEditField
        CANErrEditField_6         matlab.ui.control.NumericEditField
        VINEditField_5            matlab.ui.control.NumericEditField
        CANErrEditField_5         matlab.ui.control.NumericEditField
        VINEditField_4            matlab.ui.control.NumericEditField
        CANErrEditField_4         matlab.ui.control.NumericEditField
        VINEditField_3            matlab.ui.control.NumericEditField
        CANErrEditField_3         matlab.ui.control.NumericEditField
        VINEditField_2            matlab.ui.control.NumericEditField
        CANErrEditField_2         matlab.ui.control.NumericEditField
        CANErrEditField_1         matlab.ui.control.NumericEditField
        VINEditField_1            matlab.ui.control.NumericEditField
        ExtErrEditField_10        matlab.ui.control.NumericEditField
        ExtErrEditField_9         matlab.ui.control.NumericEditField
        ExtErrEditField_8         matlab.ui.control.NumericEditField
        ExtErrEditField_7         matlab.ui.control.NumericEditField
        ExtErrEditField_6         matlab.ui.control.NumericEditField
        ExtErrEditField_5         matlab.ui.control.NumericEditField
        ExtErrEditField_4         matlab.ui.control.NumericEditField
        ExtErrEditField_3         matlab.ui.control.NumericEditField
        ExtErrEditField_2         matlab.ui.control.NumericEditField
        ExtErrEditField_1         matlab.ui.control.NumericEditField
        NetErrEditField_10        matlab.ui.control.NumericEditField
        NetErrEditField_9         matlab.ui.control.NumericEditField
        NetErrEditField_8         matlab.ui.control.NumericEditField
        NetErrEditField_7         matlab.ui.control.NumericEditField
        NetErrEditField_6         matlab.ui.control.NumericEditField
        NetErrEditField_5         matlab.ui.control.NumericEditField
        NetErrEditField_4         matlab.ui.control.NumericEditField
        NetErrEditField_3         matlab.ui.control.NumericEditField
        NetErrEditField_2         matlab.ui.control.NumericEditField
        NetErrEditField_1         matlab.ui.control.NumericEditField
        RevEditField_9            matlab.ui.control.EditField
        RevEditField_10           matlab.ui.control.EditField
        RevEditField_5            matlab.ui.control.EditField
        RevEditField_6            matlab.ui.control.EditField
        RevEditField_7            matlab.ui.control.EditField
        RevEditField_8            matlab.ui.control.EditField
        RevEditField_4            matlab.ui.control.EditField
        RevEditField_3            matlab.ui.control.EditField
        RevEditField_2            matlab.ui.control.EditField
        RevEditField_1            matlab.ui.control.EditField
        NodeEditField_10          matlab.ui.control.NumericEditField
        NodeEditField_9           matlab.ui.control.NumericEditField
        NodeEditField_8           matlab.ui.control.NumericEditField
        NodeEditField_7           matlab.ui.control.NumericEditField
        NodeEditField_6           matlab.ui.control.NumericEditField
        NodeEditField_5           matlab.ui.control.NumericEditField
        NodeEditField_4           matlab.ui.control.NumericEditField
        NodeEditField_3           matlab.ui.control.NumericEditField
        NodeEditField_2           matlab.ui.control.NumericEditField
        NodeEditField_1           matlab.ui.control.NumericEditField
        ScanIntervalEditField     matlab.ui.control.NumericEditField
        ScanIntsLabel             matlab.ui.control.Label
        SystemNetVoltageVLabel    matlab.ui.control.Label
        PMSysVEditField           matlab.ui.control.NumericEditField
        PMLastErrEditField        matlab.ui.control.EditField
        PMTempEditField           matlab.ui.control.NumericEditField
        PMTempCheckBox            matlab.ui.control.CheckBox
        StartButton               matlab.ui.control.StateButton
        PMNetVEditField           matlab.ui.control.NumericEditField
        TestStimButton            matlab.ui.control.Button
        EnterWaitingButton        matlab.ui.control.Button
        EnterAppButton            matlab.ui.control.Button
        NetworkONBLButton         matlab.ui.control.Button
        NetworkOFFButton          matlab.ui.control.Button
        NetworkONAppButton        matlab.ui.control.Button
        PMVNETEditField           matlab.ui.control.NumericEditField
        VNETLabel                 matlab.ui.control.Label
        PMAvgPowerEditField       matlab.ui.control.NumericEditField
        PMInstPowerEditField      matlab.ui.control.NumericEditField
        PMInstPowerCheckBox       matlab.ui.control.CheckBox
        PMAvgPowerCheckBox        matlab.ui.control.CheckBox
        PMCANErrEditField         matlab.ui.control.NumericEditField
        PMCANErrCheckBox          matlab.ui.control.CheckBox
        NetworkScanCheckBox       matlab.ui.control.CheckBox
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        nnp = [];
        updateTimer = [];
        lastUpdateTime = 0;
        selpath=pwd;% get the current working directory
        LogFileName='TempLogFileName.csv'; % Save this to a temporary file
        LFileID=-1; % File ID for Log File
        CommentFileID=-1; %Comment file ID
        CommentFileName='TempCommentFileName.txt'; % Save this to a temporary file
        callingFigure=[]; %handle to calling figure
        goWaiting=0;
        goPatient=0;
        goVNET=0;
        goNetOff=0;
        goNetBoot=0;
        goNetOn=0;
        goApp=0;
        goLP=0;
        goTestStim=0;
        vinPlotYbuf=[]; % will make this 2D with the number of columns equal to the number of nodes
        vinPlotXbuf=[];
        vinSamples=40;  % start with 20, wich would be about 30 seconds
        vPlots=0; % number of vin plots
        vinArray=zeros(10,1);
        pVin=[]; %handles for vin plots
        pCounter=0;
    end
    
    properties (Access = private)

    end
    
    methods (Access = private)
        
        function updateDisplays(app)
            app.ScanIntervalEditField.Enable = 'on';
            % initialize the plot here if it is being used
            if app.PlotVinCheckBox.Value && app.VINVCheckBox.Value
                %initialize plot
%                 app.vPlots=0;
%                 for i=1:10  %need to make this a variable
%                         %
%                         nodeEdit = eval(['app.NodeEditField_' num2str(i)]);
%                         if nodeEdit.Value>0
%                             app.vPlots=app.vPlots+1;
%                         end
%                 end
%                 %app.vinPlotXbuf=nan(app.vinSamples,1); Use this later if I
%                 %plot vs time rather than sample
%                 app.UIAxes.XLim = [0 app.vinSamples];
%                 app.vinPlotXbuf=(1:app.vinSamples);
%                 app.vinPlotYbuf=nan(app.vinSamples,app.vPlots);
%                 app.pVin=plot(app.UIAxes,app.vinPlotXbuf,app.vinPlotYbuf);
            end
            while app.StartButton.Value
                t = toc(app.updateTimer);
                app.ScanIntervalEditField.Value = t - app.lastUpdateTime;
                app.lastUpdateTime = t;
                % Need to figure what to log to file
                % At the beginning of the loop, I need to enter the datetime
                if app.LogtofileButton.Value==1
                    logt=datestr(datetime('now'),'HH:MM:SS.FFF');
                    fprintf(app.LFileID,'%s, ',logt); % may want to use comma instead of space
                end
                % do I want to include columns for data if it is not logged?  Would make it easier to read later if I switch back and
                % forth
                netstat=app.nnp.read(7,'3004',1,'uint8');
                if ~isempty(netstat)
                    if length(netstat)==1
                        app.NetStatusEditField.Value=double(netstat);    %network on (1) or off (0)                        
                    else
                        app.NetStatusEditField.Value=-1;    %unknown (-1)
                    end
                else
                    app.NetStatusEditField.Value=-1;    %unknown (-1)
                end

                if app.goNetOff==1
                     app.goNetOff=0;                        
                    if app.nnp.enterWaiting()
                        if app.nnp.networkOff()
                            app.PMLastErrEditField.Value = '';
                        else
                            app.PMLastErrEditField.Value = app.nnp.lastError;
                        end
                    else
                        app.PMLastErrEditField.Value = app.nnp.lastError;
                    end
                        pause(.1);
                 end

                 if app.goNetOn==1
                   app.goNetOn=0;                   
                    if ~app.nnp.networkOn()
                        app.PMLastErrEditField.Value = app.nnp.lastError;
                    else
                        app.PMLastErrEditField.Value = '';
                    end
                    pause(.2);
                    vnet = app.nnp.getVNET();
                    if length(vnet)==1
                        app.PMVNETEditField.Value = vnet;
                    else
                        app.PMVNETEditField.Value = inf;
                    end
                 end

                if app.goNetBoot==1
                    app.goNetBoot=0;
                    if ~app.nnp.networkOnBootloader()
                        app.PMLastErrEditField.Value = app.nnp.lastError;
                    else
                        app.PMLastErrEditField.Value = '';
                    end
                    vnet = app.nnp.getVNET();
                    if length(vnet)==1
                        app.PMVNETEditField.Value = vnet;
                    else
                        app.PMVNETEditField.Value = inf;
                    end                    
                    pause(.1);
                end

                if app.goApp==1
                    app.goApp=0;
                    if ~app.nnp.enterApp() %all nodes
                        app.PMLastErrEditField.Value = app.nnp.lastError;
                    else
                        app.PMLastErrEditField.Value = 'a';
                    end
                    pause(.1);
                end

                if app.goLP==1
                    app.goLP=0;
                    lowpower(app.nnp); % call low power mlapp
                    pause(.1);
                end

                if app.goTestStim==1
                    app.goTestStim=0;
                    if ~app.nnp.enterTestStim()
                        app.PMLastErrEditField.Value = app.nnp.lastError;
                    else
                        app.PMLastErrEditField.Value = 't';
                    end
                    pause(.1);
                end


                if app.goVNET==1
                    app.goVNET=0;
                    value=app.PMVNETEditField.Value;
                    app.setVNET(value);
                    pause(.1);
                end

                if app.goWaiting==1
                    app.goWaiting=0;
                    if ~app.nnp.enterWaiting() %all nodes
                        app.PMLastErrEditField.Value = app.nnp.lastError;
                    else
                        app.PMLastErrEditField.Value = 'wa';
                    end
                    pause(.1);
                end

                if app.goPatient==1
                    app.goPatient=0;
                    if ~app.nnp.enterPatient() %all nodes
                        app.PMLastErrEditField.Value = app.nnp.lastError;
                    else
                        app.PMLastErrEditField.Value = 'pa';
                    end
                    (pause(.1));
                end
                
                resp=app.nnp.read(7,'2010',1,1,'uint8'); % read PM mode so I don't need to guess
                if length(resp)==4
                   app.PMModeEditField.Value=double(resp(2)); % use the resp value
                   switch resp(2)
                      case 1,   mode = 'Waiting';
                        case 2,   mode = 'TestPatterns';
                        case 3,   mode = 'TestStim';                                          
                        case 4,   mode = 'Stopped';
                        case 5,   mode = 'Patient';
                        case 6,   mode = 'BootCheckReset';
                        case 7,   mode = 'Test Patient Mode';
                        case 8,   mode =  'Test Features';
                        case 9,   mode = 'Test Raw';
                        case 10,   mode = 'Charging';
                        case 15,   mode =  'Not Connected';
                        otherwise,  mode = 'Unknown';
                   end
                   app.PMModeTextEditField.Value=mode; % use the resp value
                else
                       app.PMModeEditField.Value=-1;% set value to -1
                       app.PMModeTextEditField.Value='Unknown'; % did not read correctly
                end

                if app.PMAvgPowerCheckBox.Value == 1
                    value = app.nnp.getPower();
                    if ~isempty(value)
                        app.PMAvgPowerEditField.Value = -value;
                        app.PMLastErrEditField.Value = '';
                    else
                        app.PMAvgPowerEditField.Value = inf;
                        app.PMLastErrEditField.Value = app.nnp.lastError;
                    end
                    value=app.nnp.RSSI;
                    if ~isempty(value)
                        app.APRSSIEditField.Value = value;
                    end
                    drawnow();
                end
                if app.PMInstPowerCheckBox.Value == 1
                    resp = double(app.nnp.read(7, '3000', 7, 'uint16', 4)); %VREC,VSYS,VNET,LOAD
                    if length(resp)==4
                        app.PMInstPowerEditField.Value = resp(2)*resp(4)/10;
                        app.PMSysVEditField.Value = resp(2)/10;
                        app.PMNetVEditField.Value = resp(3)/10;
                        app.PMLastErrEditField.Value = '';
                    else
                        app.PMInstPowerEditField.Value = inf;
                        app.PMSysVEditField.Value = inf;
                        app.PMNetVEditField.Value = inf;
                        app.PMLastErrEditField.Value = app.nnp.lastError;
                    end
                    app.APRSSIEditField.Value = app.nnp.RSSI;
                    drawnow();
                end
                if app.PMCANErrCheckBox.Value == 1
                    value = double(app.nnp.read(7, '2500', 1, 'uint16'));
                    if ~isempty(value)
                        app.PMCANErrEditField.Value = value;
                        app.PMLastErrEditField.Value = '';
                    else
                        app.PMCANErrEditField.Value = inf;
                        app.PMLastErrEditField.Value = app.nnp.lastError;
                    end
                    drawnow();
                end
                if app.PMTempCheckBox.Value == 1
                    value = app.nnp.getTemp(7);
                    if ~isempty(value)
                        app.PMTempEditField.Value = value;
                        app.PMLastErrEditField.Value = '';
                    else
                        app.PMTempEditField.Value = inf;
                        app.PMLastErrEditField.Value = app.nnp.lastError;
                    end
                    app.APRSSIEditField.Value = app.nnp.RSSI;
                    drawnow();
                end
                if app.LogtofileButton.Value==1
                    %PM  use logPM[] as array for easier printing
                    logPM(1)= app.PMAvgPowerEditField.Value; %
                    logPM(2)= app.PMInstPowerEditField.Value; %
                    logPM(3)= app.PMSysVEditField.Value; %
                    logPM(4)= app.PMNetVEditField.Value; %
                    logPM(5)= app.NetStatusEditField.Value;
                    logPM(6)= app.PMCANErrEditField.Value; %
                    logPM(7)= app.APRSSIEditField.Value; %
                    logPM(8)= app.PMTempEditField.Value; %
                    logPM(9)= app.PMModeEditField.Value; %
                    logPMer = app.PMLastErrEditField.Value; %separate because it is text
                    
                    fprintf(app.LFileID,'%2.2f, ',logPM);
                    fprintf(app.LFileID,'%s, ',logPMer);
                end
                if app.NetworkScanCheckBox.Value == 1
                    for i=1:10  %need to make this a variable
                        %
                        nodeEdit = eval(['app.NodeEditField_' num2str(i)]);
                        revEdit = eval(['app.RevEditField_' num2str(i)]);
                        attemptEdit = eval(['app.AttemptEditField_' num2str(i)]);
                        netErrEdit = eval(['app.NetErrEditField_' num2str(i)]);
                        extErrEdit = eval(['app.ExtErrEditField_' num2str(i)]);
                        vinEdit = eval(['app.VINEditField_' num2str(i)]);
                        canErrEdit = eval(['app.CANErrEditField_' num2str(i)]);
                        tempEdit = eval(['app.TempEditField_' num2str(i)]);
                        lastErrEdit = eval(['app.LastErrEditField_' num2str(i)]);
                        
                        node = nodeEdit.Value;
                        runningApp = 0;
                        
                        if node > 0
                            [resp, error] = app.nnp.transmit(7, uint8([0, 0, node, 1, 0]), 0, hex2dec('3c'));
                            attemptEdit.Value = attemptEdit.Value + 1;
                            if error>0
                                lastErrEdit.Value = app.nnp.lastError;
                                if error == 1
                                    revEdit.BackgroundColor = [1,0,0]; % RED (network Error)
                                    netErrEdit.Value = netErrEdit.Value + 1;
                                    revEdit.Value = 'NETWORK ERR';
                                else
                                    revEdit.BackgroundColor = [1,1,0]; % YELLOW (external Error)
                                    extErrEdit.Value = extErrEdit.Value + 1;
                                    revEdit.Value = 'EXTERNAL ERR';
                                end
                            else
                                lastErrEdit.Value = '';
                                resp = double(resp);
                                if length(resp)==9 % correct response
                                    if resp(8)==node
                                        if resp(3)<=1 %BL
                                            if resp(7) == 4
                                                str = 'PG';
                                            elseif resp(7) == 5
                                                str = 'BP';
                                            else
                                                str = '?';
                                            end
                                            sn = resp(5)*256+resp(4);
                                            rev = resp(6);
                                            revEdit.BackgroundColor = [0.2,0.6,1]; % BLUE (BL Type)
                                            %                                        revEdit.Value = sprintf('%s #%3d BL %3d\n',str, sn, rev);
                                            revEdit.Value = sprintf('%s #%3d BL %3d',str, sn, rev);
                                            % LogSWrev=sprintf('%s #%3d BL %3d',str, sn, rev);
                                        elseif resp(3)==2 %running App
                                            if resp(2) == 4
                                                str = 'PG';
                                            elseif resp(2) == 5
                                                str = 'BP';
                                            else
                                                str = '?';
                                            end
                                            sn = resp(5)*256+resp(4);
                                            rev = resp(7)*256+resp(6);
                                            if isequal(str, 'PG')
                                                runningApp = 1;
                                            elseif isequal(str, 'BP')
                                                if rev >=134
                                                    runningApp = 2;
                                                else
                                                    runningApp = 3; %legacy support for diagnostics required
                                                end
                                            end
                                            
                                            revEdit.BackgroundColor = [0,1,0]; % GREEN (App Type)
                                            revEdit.Value = sprintf('%s #%3d APP%3d',str, sn,rev);
                                        else
                                            revEdit.BackgroundColor = [1,0,1]; % PURPLE (unknown App/BL Type)
                                            revEdit.Value = '?';
                                        end
                                    else
                                        revEdit.BackgroundColor = [1,0.5,0]; % ORANGE (unknown Error)
                                        extErrEdit.Value = extErrEdit.Value + 1;
                                        revEdit.Value = 'UNKNOWN ERR';
                                    end
                                else
                                    revEdit.BackgroundColor = [1,0.5,0]; % ORANGE (unknown Error)
                                        extErrEdit.Value = extErrEdit.Value + 1;
                                        revEdit.Value = 'Wrong node';
                                end
                            end
                            % I really only need response 1 and 2, so only
                            % collect those.  This allows a single response
                            % message.
                            if app.VINVCheckBox.Value
%                                pause(.2)% see if this helps reading vin
                                if runningApp == 1
                                    %PG app
                                    resp = double(app.nnp.read(node, '3000', 1, 'uint8')); %previously 5
                                    %                                     resp = double(app.nnp.read(node, '3000', 1, 2, 'uint8')); %previously 5
                                    %                                     pause(.1); % see if this helps
                                    if length(resp)==1
                                        if resp(1)~=1
                                            %enable diagnostics
                                            resp = double(app.nnp.write(node, '3000', 1, 1, 'uint8'));
                                            if ~isequal(resp, 0)
                                                lastErrEdit.Value = 'Diag could not be enabled';
                                            end
                                            vinEdit.Value = inf;
                                            %                                             vinEdit.Value = 1.1;
                                        else
                                            resp2 = double(app.nnp.read(node, '3000', 2, 'uint8')); % slower clock so split reads
                                            if length(resp2)==1
                                                vinEdit.Value = resp2*3.3/256*4;
                                            else
                                                vinEdit.Value = inf;
                                            end
                                        end
                                    else
                                        vinEdit.Value = inf;
                                        % checkhere
                                        %                                            vinEdit.Value = 1.2;
                                        %                                             vinEdit.Value = length(resp);
                                    end
                                elseif runningApp == 2
                                    %BP app
                                    resp = double(app.nnp.read(node, '3000', 1, 2, 'uint8')); %previously 5
                                    if length(resp)==2
                                        if resp(1)~=4
                                            %enable diagnostics
                                            resp = double(app.nnp.write(node, '3000', 1, 4, 'uint8'));
                                            if ~isequal(resp, 0)
                                                lastErrEdit.Value = 'Diag could not be enabled';
                                            end
                                            vinEdit.Value = inf;
%                                             vinEdit.Value = 1.3;
                                        else
                                            vinEdit.Value = resp(2)*3.3/256*4;
                                        end
                                    else
                                        vinEdit.Value = inf;
%                                         vinEdit.Value = 1.4;
                                    end
                                elseif runningApp == 3
                                    %legacy support for old BP apps
                                    resp = double(app.nnp.read(node, '3000', 1, 'uint8'));
                                    if ~isequal(resp, 1)
                                        %enable diagnostics
                                        resp = double(app.nnp.write(node, '3000', 1, 1, 'uint8'));
                                        if ~isequal(resp, 0)
                                            lastErrEdit.Value = 'Diag could not be enabled';
                                            vinEdit.Value = inf;
%                                             vinEdit.Value = 1.5;
                                        end
                                    else
                                        resp = double(app.nnp.read(node, '3510', 2, 'uint8'));
                                        if length(resp)==1
                                            vinEdit.Value = resp*3.3/256*4;
                                        else
                                            vinEdit.Value = inf;
%                                             vinEdit.Value = 1.6;
                                        end
                                    end
                                else
                                    vinEdit.Value = inf;
%                                     vinEdit.Value = 1.7;
                                end
                                app.vinArray(i)=vinEdit.Value; %update the plot array
                            end %VIN
                            
                            if app.CANErrCheckBox.Value
                                if runningApp
                                    resp = double(app.nnp.read(node, '2500', 1, 'uint16'));
                                    if length(resp)==1
                                        canErrEdit.Value = resp;
                                    else
                                        canErrEdit.Value = inf;
                                    end
                                else
                                    canErrEdit.Value = inf;
                                end
                            end
                            if app.TempCheckBox.Value
                                if runningApp
                                    resp = app.nnp.getTemp(node);
                                    if ~isempty(resp)
                                        tempEdit.Value = resp;
                                    else
                                        tempEdit.Value = inf;
                                    end
                                else
                                    tempEdit.Value = inf;
                                end
                            end
                            
                            if app.LogtofileButton.Value==1
                                %net use logNet as array for easier printing
                                logNet(1) = nodeEdit.Value; % d
                                logNet(2) = attemptEdit.Value;  % d
                                logNet(3) = netErrEdit.Value; % d
                                logNet(4) = extErrEdit.Value; % d
                                logNet(5) = vinEdit.Value; % f
                                logNet(6) = canErrEdit.Value; % f
                                logNet(7) = tempEdit.Value; % f
                                logSWrev = revEdit.Value; %s
                                logNeter = lastErrEdit.Value ; %separate because it is text
                                % revEdit.Value
                                
                                fprintf(app.LFileID,'%d, %s, %d, %d, %d, %2.2f, %2.2f, %2.2f, ',logNet(1),logSWrev,logNet(2:7));

                                fprintf(app.LFileID,'%s, ',logNeter);
                            end
                        end %end node >0
                        drawnow();
                        pause(0.001); %leave time to handle button presses
                        
                    end %end node loop
                end %end net scan
                if app.LogtofileButton.Value==1
                    fprintf(app.LFileID,'\n');
                end
                %
                app.APRSSIEditField.Value = app.nnp.RSSI;
                drawnow();
                if app.PlotVinCheckBox.Value && app.VINVCheckBox.Value
                %update the plot
                app.updatePlots();
                 end
                %
                pause(0.01); %leave time to handle button presses
            end
            app.ScanIntervalEditField.Enable = 'off';
            
        end
        
        
        function setVNET(app,value)
          %range check
            if value>9.5
                value = 9.5;
                app.PMVNETEditField.Value;
            elseif value< 4.7
                value = 4.7;
                app.PMVNETEditField.Value;
            end
            %set it
            app.nnp.setVNET(value);
            pause(.1);
            %read it back
            vnet = app.nnp.getVNET();

            if ~isempty(vnet)
                app.PMVNETEditField.Value = vnet;
            else
                app.PMVNETEditField.Value = inf;
            end                 
        end
        
        function resetVinPlots(app)
                app.vPlots=0;
                for i=1:10  %need to make this a variable
                        %
                        nodeEdit = eval(['app.NodeEditField_' num2str(i)]);
                        if nodeEdit.Value>0
                            app.vPlots=app.vPlots+1;
                        end
                end
                %app.vinPlotXbuf=nan(app.vinSamples,1); Use this later if I
                %plot vs time rather than sample
                app.UIAxes.XLim = [0 app.vinSamples];
                app.vinPlotXbuf=(1:app.vinSamples);
                app.vinPlotYbuf=nan(app.vinSamples,app.vPlots);
                app.pVin=plot(app.UIAxes,app.vinPlotXbuf,app.vinPlotYbuf);
                app.pCounter=0;

                ddStrings=cell(1,app.vPlots);
                for i=1:app.vPlots
                      nodeEdit = eval(['app.NodeEditField_' num2str(i)]);
                      ddStrings{i}=num2str(nodeEdit.Value);
                end
                app.highlightDropDown.Items=ddStrings;
                app.highlightDropDown.ItemsData=[1:app.vPlots];
                app.setPlotHightlight();
        end
    end
    
    methods (Access = public)
        
        function NewLogFileName(app)
            userstring=sprintf('P%d_',int16(app.UserEditField.Value));
            timestring=sprintf('_%02.0f',datevec(now));
            %
            app.LogFilenameEditField.Value=[userstring,'NSLog',timestring,'.csv'];
            %
            app.LogFileName=fullfile(app.FolderEditField.Value,app.LogFilenameEditField.Value);
        end
        
        function NewLogFolderName(app)
            newpath = uigetdir(app.selpath,'Folder to store Log files');
            if ischar(newpath) % if canceled, this is a number 0
                if isfolder(newpath)  % can only use this with character array
                    app.selpath=newpath;  % update the path if a new one given
                    app.FolderEditField.Value=app.selpath;
                    app.FolderButton.BackgroundColor=[0.96,0.96,0.96]; % from [1,.9,.6]
                    % Create or open a comment file to add comments
                    % Create the file name but don't open until the comment
                    % is added, then append.
                    parts = strsplit(newpath, filesep);
                    commentfile=[parts{end},'_NSComments.txt'];
                    app.CommentFileNameEditField.Value=commentfile;
                    app.CommentFileName=fullfile(app.selpath,commentfile);
                end
            end
            figure(app.NetworkScannerUIFigure); % Restore focus to this window
            %
        end
        
        function results = makeHeader(app)
            results='Date,PMPowerAv,PMPowerInst,Vsys,Vnet,NetOn,PMCANErr,APRSSI,PMtemp,PMmode,PMErr';
            % cycle through each of the nodes listed and generate a set of
            % headers based upon the non-zero nodes
            for i=1:10
                              %
                nodeEdit = eval(['app.NodeEditField_' num2str(i)]);
                
                node = nodeEdit.Value;
                
                if node > 0
                    nodestr=num2str(node); 
                    newstr=sprintf(',Node%s,SWrev%s,Att%s,NetErr%s,ExtErr%s,VIN%s,CANErr%s,Ntemp%s,Err%s',nodestr,nodestr,nodestr,nodestr,nodestr,nodestr,nodestr,nodestr,nodestr);
                    results=[results,newstr];%build the string by parts
                end
            end
            results=sprintf([results,'\n']);  %add carriage return
        end
        
        function updatePlots(app)
            % updatePlots is called from updateDisplays
            app.pCounter=app.pCounter+1;
            for i=1:app.vPlots            
                if app.pCounter<=app.vinSamples
                    app.vinPlotYbuf(app.pCounter,i)=app.vinArray(i);
                else
                    app.vinPlotYbuf(1:end-1,i)=app.vinPlotYbuf(2:end,i);
                    app.vinPlotYbuf(end,i)=app.vinArray(i);
                end
                app.pVin(i).YData=app.vinPlotYbuf(:,i);
            end
        end
        
        function setPlotHightlight(app)
            % lines are app.pVin()
            value = app.highlightDropDown.Value;
            for i=1:app.vPlots
                if i==value
                app.pVin(i).LineWidth=5;
                else
                 app.pVin(i).LineWidth=1;    
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin<2
                NNP=[];
            else
                NNP=varargin{1};
            end
            if isempty(NNP)
                app.nnp = NNPAPI();
                if isempty(app.nnp.port)
                    msgbox('No port chosen for NNP Access Point')
                    delete(app.nnp)
                    return
                end
            else
                app.nnp = NNP;
            end
            if nargin>2
                app.callingFigure=varargin{2};
            end

            
            %Get VNET
            vnet = app.nnp.getVNET();
            if ~isempty(vnet)
                app.PMVNETEditField.Value = vnet;
            else
                app.PMVNETEditField.Value = inf;
            end
            app.FolderEditField.Value=app.selpath;  % start with current path
            
            %Add user data to the NodeEditField so we can determine source in single callback
            app.NodeEditField_1.UserData = 1;
            app.NodeEditField_2.UserData = 2;
            app.NodeEditField_3.UserData = 3;
            app.NodeEditField_4.UserData = 4;
            app.NodeEditField_5.UserData = 5;
            app.NodeEditField_6.UserData = 6;
            app.NodeEditField_7.UserData = 7;
            app.NodeEditField_8.UserData = 8;
            app.NodeEditField_9.UserData = 9;
            app.NodeEditField_10.UserData = 10;
            app.LFileID=-1; %start with Fild ID that won't cause error when testing
            app.resetVinPlots();
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            value = app.StartButton.Value;
            if value == 1
                app.StartButton.Text = {'Running';'Push to';'Stop'};
                app.StartButton.BackgroundColor=[1.00,0.9,0.90];
                app.StartButton.FontSize=14;
                app.LogtofileButton.Enable=0; %Starting log midstream gets columns out of order
                app.NewLogFile.Enable=0;
                app.FcloseButton.Enable=0;
                app.ResetPMErrCnt_Button.Enable=0;
                app.updateTimer = tic;
                app.lastUpdateTime = 0;
                app.updateDisplays();
                %app.ScanIntervalEditField.Enable = 'on';

            else
                app.StartButton.Text = 'Start';
                app.StartButton.FontSize=22;
                app.StartButton.BackgroundColor=[0.51,0.96,0.51];
                %app.ScanIntervalEditField.Enable = 'off';
                app.LogtofileButton.Enable=1;
                app.NewLogFile.Enable=1;
                app.FcloseButton.Enable=1;
                app.ResetPMErrCnt_Button.Enable=1;
            end
            
        end

        % Button pushed function: NetworkONAppButton
        function NetworkONAppButtonPushed(app, event)
        if app.StartButton.Value==1
                app.goNetOn=1;
        else
            if ~app.nnp.networkOn()
                app.PMLastErrEditField.Value = app.nnp.lastError;
            else
                app.PMLastErrEditField.Value = '';
            end
            pause(.2);
            vnet = app.nnp.getVNET();
            if length(vnet)==1
                app.PMVNETEditField.Value = vnet;
            else
                app.PMVNETEditField.Value = inf;
            end
        end
        end

        % Button pushed function: NetworkONBLButton
        function NetworkONBLButtonPushed(app, event)
           if app.StartButton.Value==1
                app.goNetBoot=1;
           else
                if ~app.nnp.networkOnBootloader()
                app.PMLastErrEditField.Value = app.nnp.lastError;
                else
                app.PMLastErrEditField.Value = '';
                end
                vnet = app.nnp.getVNET();
                if length(vnet)==1
                    app.PMVNETEditField.Value = vnet;
                else
                    app.PMVNETEditField.Value = inf;
                end
            end
        end

        % Value changed function: PMVNETEditField
        function PMVNETEditFieldValueChanged(app, event)
            value = app.PMVNETEditField.Value;
            if app.StartButton.Value==1;
                app.goVNET=1;
            else
                app.setVNET(value);
            end
            
%             %range check
%             if value>9.5
%                 value = 9.5;
%                 app.PMVNETEditField.Value;
%             elseif value< 4.7
%                 value = 4.7;
%                 app.PMVNETEditField.Value;
%             end
%             %set it
%             app.nnp.setVNET(value);
%             pause(.1);
%             %read it back
%             vnet = app.nnp.getVNET();
% 
%             if ~isempty(vnet)
%                 app.PMVNETEditField.Value = vnet;
%             else
%                 app.PMVNETEditField.Value = inf;
%             end               
        end

        % Button pushed function: NetworkOFFButton
        function NetworkOFFButtonPushed(app, event)
            if app.StartButton.Value==1
                app.goNetOff=1;
            else
                if app.nnp.enterWaiting()
                    if app.nnp.networkOff()
                        app.PMLastErrEditField.Value = '';
                    else
                        app.PMLastErrEditField.Value = app.nnp.lastError;
                    end
                else
                    app.PMLastErrEditField.Value = app.nnp.lastError;
                end
            end
        end

        % Button pushed function: EnterAppButton
        function EnterAppButtonPushed(app, event)
         if app.StartButton.Value==1
                app.goApp=1;
        else
            if ~app.nnp.enterApp() %all nodes
                app.PMLastErrEditField.Value = app.nnp.lastError;
            else
                app.PMLastErrEditField.Value = 'a';
            end
         end
        end

        % Button pushed function: EnterWaitingButton
        function EnterWaitingButtonPushed(app, event)
            if app.StartButton.Value==1
                app.goWaiting=1;
            else
                if ~app.nnp.enterWaiting() %all nodes
                    app.PMLastErrEditField.Value = app.nnp.lastError;
                else
                    app.PMLastErrEditField.Value = 'w';
                end
            end
        end

        % Button pushed function: TestStimButton
        function TestStimButtonPushed(app, event)
             if app.StartButton.Value==1
                app.goTestStim=1;
             else    
                 if ~app.nnp.enterTestStim()
                    app.PMLastErrEditField.Value = app.nnp.lastError;
                else
                     app.PMLastErrEditField.Value = 't';
                 end
            end
        end

        % Value changed function: PMAvgPowerCheckBox
        function PMAvgPowerCheckBoxValueChanged(app, event)
            if app.PMAvgPowerCheckBox.Value
                app.PMAvgPowerEditField.Enable = 'on';
            else
                app.PMAvgPowerEditField.Enable = 'off';
            end
        end

        % Value changed function: PMInstPowerCheckBox
        function PMInstPowerCheckBoxValueChanged(app, event)
            if app.PMInstPowerCheckBox.Value
                app.PMInstPowerEditField.Enable = 'on';
                app.PMSysVEditField.Enable = 'on';
                app.PMNetVEditField.Enable = 'on';
            else
                app.PMInstPowerEditField.Enable = 'off';
                app.PMSysVEditField.Enable = 'off';
                app.PMNetVEditField.Enable = 'off';
            end
        end

        % Value changed function: PMCANErrCheckBox
        function PMCANErrCheckBoxValueChanged(app, event)
            if app.PMCANErrCheckBox.Value
                app.PMCANErrEditField.Enable = 'on';
            else
                app.PMCANErrEditField.Enable = 'off';
            end
        end

        % Value changed function: PMTempCheckBox
        function PMTempCheckBoxValueChanged(app, event)
            if app.PMTempCheckBox.Value
                app.PMTempEditField.Enable = 'on';
            else
                app.PMTempEditField.Enable = 'off';
            end
        end

        % Button pushed function: ResetCountersButton
        function ResetCountersButtonPushed(app, event)
            for i=1:10
                attemptEdit = eval(['app.AttemptEditField_' num2str(i)]);
                netErrEdit = eval(['app.NetErrEditField_' num2str(i)]);
                extErrEdit = eval(['app.ExtErrEditField_' num2str(i)]);
                attemptEdit.Value = 0;
                netErrEdit.Value = 0;
                extErrEdit.Value = 0;
            end
        end

        % Value changed function: NodeEditField_1, NodeEditField_10, 
        % NodeEditField_2, NodeEditField_3, NodeEditField_4, 
        % NodeEditField_5, NodeEditField_6, NodeEditField_7, 
        % NodeEditField_8, NodeEditField_9
        function NodeEditField_ValueChanged(app, event)
            i = event.Source.UserData;
            nodeEdit = eval(['app.NodeEditField_' num2str(i)]);
            revEdit = eval(['app.RevEditField_' num2str(i)]);
            attemptEdit = eval(['app.AttemptEditField_' num2str(i)]);
            netErrEdit = eval(['app.NetErrEditField_' num2str(i)]);
            extErrEdit = eval(['app.ExtErrEditField_' num2str(i)]);
            vinEdit = eval(['app.VINEditField_' num2str(i)]);
            canErrEdit = eval(['app.CANErrEditField_' num2str(i)]);
            tempEdit = eval(['app.TempEditField_' num2str(i)]);
            lastErrEdit = eval(['app.LastErrEditField_' num2str(i)]);
            
            %refresh views
            revEdit.Value = '';
            revEdit.BackgroundColor = [1,1,1];
            attemptEdit.Value = 0;
            netErrEdit.Value = 0;
            extErrEdit.Value = 0;
            vinEdit.Value = inf;
            canErrEdit.Value = inf;
            tempEdit.Value = inf;
            lastErrEdit.Value = '';
            
            %enable or disable based on node==0
            if nodeEdit.Value>0
                enable = 'on';
            else
                enable = 'off';
            end
            revEdit.Enable = enable;
            attemptEdit.Enable = enable;
            netErrEdit.Enable = enable;
            extErrEdit.Enable = enable;
            if app.VINVCheckBox.Value
                vinEdit.Enable = enable;
            else
                vinEdit.Enable = 'off';
            end
            if app.CANErrCheckBox.Value
                canErrEdit.Enable = enable;
            else
                canErrEdit.Enable = 'off';
            end
            if app.TempCheckBox.Value
                tempEdit.Enable = enable;
            else
                tempEdit.Enable = 'off';
            end
            lastErrEdit.Enable = enable;
            
        end

        % Value changed function: VINVCheckBox
        function VINVCheckBoxValueChanged(app, event)
            for i=1:10
                nodeEdit = eval(['app.NodeEditField_' num2str(i)]);
                if nodeEdit.Value > 0
                    nedit = eval(['app.VINEditField_' num2str(i)]);
                    if app.VINVCheckBox.Value
                        nedit.Enable = 'on';
                    else
                        nedit.Enable = 'off';
                        nedit.Value=inf;
                    end
                end
            end
        end

        % Value changed function: CANErrCheckBox
        function CANErrCheckBoxValueChanged(app, event)
            for i=1:10
                nodeEdit = eval(['app.NodeEditField_' num2str(i)]);
                if nodeEdit.Value > 0
                    edit = eval(['app.CANErrEditField_' num2str(i)]);
                    if app.CANErrCheckBox.Value
                        edit.Enable = 'on';
                    else
                        edit.Enable = 'off';
                    end
                end
            end
        end

        % Value changed function: TempCheckBox
        function TempCheckBoxValueChanged(app, event)
            for i=1:10
                nodeEdit = eval(['app.NodeEditField_' num2str(i)]);
                if nodeEdit.Value > 0
                    edit = eval(['app.TempEditField_' num2str(i)]);
                    if app.TempCheckBox.Value
                        edit.Enable = 'on';
                    else
                        edit.Enable = 'off';
                    end
                end
            end
        end

        % Value changed function: NetworkScanCheckBox
        function NetworkScanCheckBoxValueChanged(app, event)
            if app.NetworkScanCheckBox.Value
                app.NetPanel.Visible = 'on';
            else
                app.NetPanel.Visible = 'off';
            end
        end

        % Button pushed function: GetStatusButton
        function GetStatusButtonPushed(app, event)
            [modes, temp, vsys, net, rssi] = app.nnp.getStatus();
            if ~isempty(modes)
                if net
                    str = sprintf('Network is ON');
                else
                    str = sprintf('Network is OFF');
                end
                str = [str, sprintf('\n\nPM Temp: %4.1f⁰C\nRSSI: %4.1fdBm\nVSYS: %4.1fV\nPM mode: %s\n\n', temp, rssi, vsys, modes{7})];

                %insert here a read of battery voltages from 3000.0D
                bat=double(app.nnp.read(7,'3000',13,'uint8')); % read revision number to test communications
                if length(bat)<20

                else
                    batV1 = (bat( 7)+bat( 8)*256)/1000; 
                    batV2 = (bat( 9)+bat(10)*256)/1000; 
                    batV3 = (bat(11)+bat(12)*256)/1000; %in V
   
                    str = [str, sprintf('Vbatt: %4.2f,  %4.2f,  %4.2f\n\n',batV1,batV2,batV3)];
                end
                if net
                    str = [str, sprintf('Note: Nodes not listed on network may be in bootloader mode.\nNodes also may take up to 2s to update.\n\n')];
                    for i=[1:6 8:length(modes)]
                        str=[str, sprintf('Node %2d: %s\n', i, modes{i})];
                    end
                else
                    str = [str,'Note: Nodes not listed because network is off'];
                end
                
                h = msgbox(str, 'Get Status', 'modal');
                uiwait(h);
            else
                h = msgbox('Could not get Status', 'Get Status Error', 'error', 'modal');
                uiwait(h);
            end
            
            
        end

        % Button pushed function: FolderButton
        function FolderButtonPushed(app, event)
            NewLogFolderName(app);
            NewLogFileName(app);
        end

        % Value changed function: LogtofileButton
        function LogtofileButtonValueChanged(app, event)
            value = app.LogtofileButton.Value;

            if value>0
                app.LogtofileButton.BackgroundColor='g';
                app.LogtofileButton.Text=sprintf('Log is\n On');
                % add a check here for a valid folder and call
                % FolderButtonPushed if not
                if ~isfolder(app.FolderEditField.Value)
                    NewLogFolderName(app);
                end
                % Add check for valid file FID, and see if it is open
                % if not, then check for valid filename and create on if
                % not and open the file in append mode.
                if ~isfile(app.LogFileName)
                    NewLogFileName(app);    % create file name
                end
                % check whether file ID exists
                if isempty(fopen(app.LFileID)) % check whether file is already open, 1 means not open
                    app.LFileID=fopen(app.LogFileName,'a'); % open file in append mode
                                   % if file is newly opened, then write header.
                                   header=app.makeHeader;
                                   fprintf(app.LFileID,header);
                    %fprintf(app.LFileID,'Date,PMPowerAv,PMPowerInst,Vsys,Vnet,PMCANErr,APRSSI,PMtemp,PMErr,Node1,Att1,NetErr1,ExtErr1,VIN1,CANErr1,Ntemp1,Err1,Node2,Att2,NetErr2,ExtErr2,VIN2,CANErr2,Ntemp2,Err2,Node3,Att3,NetErr3,ExtErr3,VIN3,CANErr3,Ntemp3,Err3,Node4,Att4,NetErr4,ExtErr4,VIN4,CANErr4,Ntemp4,Err4,Node5,Att5,NetErr5,ExtErr5,VIN5,CANErr5,Ntemp5,Err5,Node6,Att6,NetErr6,ExtErr6,VIN6,CANErr6,Ntemp6,Err6,Node9,Att9,NetErr9,ExtErr9,VIN9,CANErr9,Ntemp9,Err9\n');
                end
            else
                app.LogtofileButton.BackgroundColor=[.96,.96,.96];
                app.LogtofileButton.Text=sprintf('Log is\n Off');
                % Note that I don't close the file here because I may want
                % to append to it.
                app.NewLogFile.Enable=1;
                app.FcloseButton.Enable=1;
            end
        end

        % Button pushed function: NewLogFile
        function NewLogFileButtonPushed(app, event)
            % if current fle is open, want to close it first
            if ~isempty(fopen(app.LFileID)) %file is open
                fclose(app.LFileID); % close file
            end
            % Get new file name
            NewLogFileName(app);
            % Open new file
            if isempty(fopen(app.LFileID)) %file is not open
                app.LFileID=fopen(app.LogFileName,'a'); % open file in append mode
                % if file is newly opened, then write header.
                header=app.makeHeader;
                fprintf(app.LFileID,header);
%                 fprintf(app.LFileID,'Date,PMPowerAv,PMPowerInst,Vsys,Vnet,PMCANErr,APRSSI,PMtemp,PMErr,Node1,Att1,NetErr1,ExtErr1,VIN1,CANErr1,Ntemp1,Err1,Node2,Att2,NetErr2,ExtErr2,VIN2,CANErr2,Ntemp2,Err2,Node3,Att3,NetErr3,ExtErr3,VIN3,CANErr3,Ntemp3,Err3,Node4,Att4,NetErr4,ExtErr4,VIN4,CANErr4,Ntemp4,Err4,Node5,Att5,NetErr5,ExtErr5,VIN5,CANErr5,Ntemp5,Err5,Node6,Att6,NetErr6,ExtErr6,VIN6,CANErr6,Ntemp6,Err6,Node9,Att9,NetErr9,ExtErr9,VIN9,CANErr9,Ntemp9,Err9\n');
            end
        end

        % Close request function: NetworkScannerUIFigure
        function NetworkScannerUIFigureCloseRequest(app, event)
            %             if ~isempty(fopen(app.LFileID)) %file is open
            %                 fclose(app.LogFileName); % close file
            %             end
            fclose('all'); %close open files
            if ishandle(app.callingFigure) % check that is is valid
                figure(app.callingFigure); % bring to front
            end
            delete(app)

        end

        % Button pushed function: FcloseButton
        function FcloseButtonPushed(app, event)
            % Only need this if I want to close the file and view it externally
            if exist('app.LFileID','var')==1  %file id exists
                if ~isempty(fopen(app.LFileID)) %file is open
                    fclose(app.LFileID); % close file
                end
            end
        end

        % Button pushed function: LowPower
        function LowPowerButtonPushed(app, event)
            if app.StartButton.Value==1
                app.goLP=1;
            else
                lowpower(app.nnp); % call low power mlapp
            end
        end

        % Button pushed function: WriteButton
        function WriteButtonPushed(app, event)
            % Open file in append mode and create ID 
            app.CommentFileID=fopen(app.CommentFileName,'a');
            % Write date and time
            fprintf(app.CommentFileID,'\n%s\n',datestr(now));
            % Write log file for reference
            fprintf(app.CommentFileID,'%s\n',app.LogFilenameEditField.Value);
            % Write comment from text area
            AL=size(app.CommentsTextArea.Value,1);
            for i=1:AL
            fprintf(app.CommentFileID,'%s\n',app.CommentsTextArea.Value{i});
            end
            % Close file
            fclose(app.CommentFileID);
            % Clear comment text area
            app.CommentsTextArea.Value='';

        end

        % Button pushed function: EnterPatientButton
        function EnterPatientButtonPushed(app, event)
            % patient mode so I can activate FG
            if app.StartButton.Value==1
                app.goPatient=1;
            else
                if ~app.nnp.enterPatient()
                    app.PMLastErrEditField.Value = app.nnp.lastError;
                else
                    app.PMLastErrEditField.Value = 'p';
                end
            end           
        end

        % Button pushed function: TestFeaturesButton
        function TestFeaturesButtonPushed(app, event)
            if ~app.nnp.enterTestFeatures()
                app.PMLastErrEditField.Value = app.nnp.lastError;
            else
                app.PMLastErrEditField.Value = 'f';
            end            
        end

        % Menu selected function: DisableMenu
        function DisableMenuSelected(app, event)
            app.nnp.enterWaiting();
            response=app.nnp.nmt(7, '8F'); %disable scripts
            if response==hex2dec('8F')
                message='Scripts Disabled';
            else
                message=app.nnp.lastError;
            end
            app.PMLastErrEditField.Value = message;
        end

        % Menu selected function: EnableMenu
        function EnableMenuSelected(app, event)
            app.nnp.enterWaiting();
            response=app.nnp.nmt(7, '8E'); %enable scripts
            if response==hex2dec('8E')
                message='Scripts Enabled';
            else
                message=app.nnp.lastError;
            end
            app.PMLastErrEditField.Value = message;
        end

        % Menu selected function: StartupMenu
        function StartupMenuSelected(app, event)
             response=app.nnp.nmt(7, '8E'); %enable scripts
            if response==hex2dec('8E')
                message='Scripts Enabled';
                response=app.nnp.nmt(7,'B3',7); % Run startup script
                if response==hex2dec('B3')
                    message='Scripts enabled and Startup Ran';

                else
                    message=['Scripts Enabled, but ',app.nnp.lastError];

                end
            else
                message=app.nnp.lastError;
            end
            app.PMLastErrEditField.Value = message;
        end

        % Menu selected function: DisableAccPDOsMenu
        function DisableAccPDOsMenuSelected(app, event)
            for i=1:10
                nodeEdit = eval(['app.NodeEditField_' num2str(i)]);
                if nodeEdit.Value > 0
                    node=nodeEdit.Value;
                    send=253;
                    resp=app.nnp.write(node,'1800',2,send,'uint8');
                if isequal(resp, 0)
                   message=sprintf('Disable PDO node %d',node);
                   app.nnp.nmt(node, '0a');
                else
                    message=sprintf('Fail Disable PDO node %d',node);
                end
                app.PMLastErrEditField.Value = message;
                pause(.5);
                end
            end
        end

        % Button pushed function: ResetVinPlotButton
        function ResetVinPlotButtonPushed(app, event)
            app.resetVinPlots();
        end

        % Value changed function: highlightDropDown
        function highlightDropDownValueChanged(app, event)
           app.setPlotHightlight();
            
        end

        % Button pushed function: ResetPMErrCnt_Button
        function ResetPMErrCnt_ButtonPushed(app, event)
            % NMT 0x98 to reset the PM CAN Error counter
            response=app.nnp.nmt(7, '98'); % Reset PM CAN Error Counter
            if response==hex2dec('98')
                message='PM CAN Error Reset';  % may want to leave blank if no error
            else
                message=app.nnp.lastError;
            end
            app.PMLastErrEditField.Value = message;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NetworkScannerUIFigure = uifigure('Visible', 'off');
            app.NetworkScannerUIFigure.Name = 'Network Scanner';
            app.NetworkScannerUIFigure.CloseRequestFcn = createCallbackFcn(app, @NetworkScannerUIFigureCloseRequest, true);
            app.ScriptsMenu = uimenu(app.NetworkScannerUIFigure);
            app.ScriptsMenu.Text = 'Scripts';
            app.DisableMenu = uimenu(app.ScriptsMenu);
            app.DisableMenu.MenuSelectedFcn = createCallbackFcn(app, @DisableMenuSelected, true);
            app.DisableMenu.Text = 'Disable';
            app.EnableMenu = uimenu(app.ScriptsMenu);
            app.EnableMenu.MenuSelectedFcn = createCallbackFcn(app, @EnableMenuSelected, true);
            app.EnableMenu.Text = 'Enable';
            app.StartupMenu = uimenu(app.ScriptsMenu);
            app.StartupMenu.MenuSelectedFcn = createCallbackFcn(app, @StartupMenuSelected, true);
            app.StartupMenu.Text = 'Startup';
            app.AccMenu = uimenu(app.NetworkScannerUIFigure);
            app.AccMenu.Text = 'Acc';
            app.DisableAccPDOsMenu = uimenu(app.AccMenu);
            app.DisableAccPDOsMenu.MenuSelectedFcn = createCallbackFcn(app, @DisableAccPDOsMenuSelected, true);
            app.DisableAccPDOsMenu.Text = 'Disable Acc PDOs';
            app.UIAxes = uiaxes(app.NetworkScannerUIFigure);
            title(app.UIAxes, 'Vin')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Vin (V)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 20];
            app.UIAxes.YLim = [5.5 9.5];
            app.NetworkScanCheckBox = uicheckbox(app.NetworkScannerUIFigure);
            app.NetworkScanCheckBox.ValueChangedFcn = createCallbackFcn(app, @NetworkScanCheckBoxValueChanged, true);
            app.NetworkScanCheckBox.Text = 'Network Scan';
            app.NetworkScanCheckBox.Value = true;
            app.PMCANErrCheckBox = uicheckbox(app.NetworkScannerUIFigure);
            app.PMCANErrCheckBox.ValueChangedFcn = createCallbackFcn(app, @PMCANErrCheckBoxValueChanged, true);
            app.PMCANErrCheckBox.Text = 'PM CAN Error Counter';
            app.PMCANErrCheckBox.Value = true;
            app.PMCANErrEditField = uieditfield(app.NetworkScannerUIFigure, 'numeric');
            app.PMCANErrEditField.ValueDisplayFormat = '%.0f';
            app.PMCANErrEditField.Editable = 'off';
            app.PMCANErrEditField.Value = Inf;
            app.PMAvgPowerCheckBox = uicheckbox(app.NetworkScannerUIFigure);
            app.PMAvgPowerCheckBox.ValueChangedFcn = createCallbackFcn(app, @PMAvgPowerCheckBoxValueChanged, true);
            app.PMAvgPowerCheckBox.Text = 'PM Average Power (mW)';
            app.PMAvgPowerCheckBox.Value = true;
            app.PMInstPowerCheckBox = uicheckbox(app.NetworkScannerUIFigure);
            app.PMInstPowerCheckBox.ValueChangedFcn = createCallbackFcn(app, @PMInstPowerCheckBoxValueChanged, true);
            app.PMInstPowerCheckBox.Text = 'PM Instant Power (mW)';
            app.PMInstPowerCheckBox.Value = true;
            app.PMInstPowerEditField = uieditfield(app.NetworkScannerUIFigure, 'numeric');
            app.PMInstPowerEditField.ValueDisplayFormat = '%.0f';
            app.PMInstPowerEditField.Editable = 'off';
            app.PMInstPowerEditField.Value = Inf;
            app.PMAvgPowerEditField = uieditfield(app.NetworkScannerUIFigure, 'numeric');
            app.PMAvgPowerEditField.ValueDisplayFormat = '%.0f';
            app.PMAvgPowerEditField.Editable = 'off';
            app.PMAvgPowerEditField.Value = Inf;
            app.VNETLabel = uilabel(app.NetworkScannerUIFigure);
            app.VNETLabel.Text = 'VNET';
            app.PMVNETEditField = uieditfield(app.NetworkScannerUIFigure, 'numeric');
            app.PMVNETEditField.ValueDisplayFormat = '%.1f';
            app.PMVNETEditField.ValueChangedFcn = createCallbackFcn(app, @PMVNETEditFieldValueChanged, true);
            app.PMVNETEditField.Value = Inf;
            app.NetworkONAppButton = uibutton(app.NetworkScannerUIFigure, 'push');
            app.NetworkONAppButton.ButtonPushedFcn = createCallbackFcn(app, @NetworkONAppButtonPushed, true);
            app.NetworkONAppButton.Text = {'Network '; 'ON App'};
            app.NetworkOFFButton = uibutton(app.NetworkScannerUIFigure, 'push');
            app.NetworkOFFButton.ButtonPushedFcn = createCallbackFcn(app, @NetworkOFFButtonPushed, true);
            app.NetworkOFFButton.Text = {'Network '; 'OFF'};
            app.NetworkONBLButton = uibutton(app.NetworkScannerUIFigure, 'push');
            app.NetworkONBLButton.ButtonPushedFcn = createCallbackFcn(app, @NetworkONBLButtonPushed, true);
            app.NetworkONBLButton.Text = {'Network ON'; 'Bootloader'};
            app.EnterAppButton = uibutton(app.NetworkScannerUIFigure, 'push');
            app.EnterAppButton.ButtonPushedFcn = createCallbackFcn(app, @EnterAppButtonPushed, true);
            app.EnterAppButton.Text = {'Enter '; 'App'};
            app.EnterWaitingButton = uibutton(app.NetworkScannerUIFigure, 'push');
            app.EnterWaitingButton.ButtonPushedFcn = createCallbackFcn(app, @EnterWaitingButtonPushed, true);
            app.EnterWaitingButton.Text = {'Enter '; 'Waiting'};
            app.TestStimButton = uibutton(app.NetworkScannerUIFigure, 'push');
            app.TestStimButton.ButtonPushedFcn = createCallbackFcn(app, @TestStimButtonPushed, true);
            app.TestStimButton.Tooltip = {'TestStim or ''Y Manual'' mode'};
            app.TestStimButton.Text = {'Test'; 'Stim'};
            app.PMNetVEditField = uieditfield(app.NetworkScannerUIFigure, 'numeric');
            app.PMNetVEditField.ValueDisplayFormat = '%.1f';
            app.PMNetVEditField.Editable = 'off';
            app.PMNetVEditField.Value = Inf;
            app.StartButton = uibutton(app.NetworkScannerUIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.PMTempCheckBox = uicheckbox(app.NetworkScannerUIFigure);
            app.PMTempCheckBox.ValueChangedFcn = createCallbackFcn(app, @PMTempCheckBoxValueChanged, true);
            app.PMTempCheckBox.Text = 'PM Temperature (⁰C)';
            app.PMTempCheckBox.Value = true;
            app.PMTempEditField = uieditfield(app.NetworkScannerUIFigure, 'numeric');
            app.PMTempEditField.ValueDisplayFormat = '%.1f';
            app.PMTempEditField.Editable = 'off';
            app.PMTempEditField.Value = Inf;
            app.PMLastErrEditField = uieditfield(app.NetworkScannerUIFigure, 'text');
            app.PMLastErrEditField.Editable = 'off';
            app.PMSysVEditField = uieditfield(app.NetworkScannerUIFigure, 'numeric');
            app.PMSysVEditField.ValueDisplayFormat = '%.1f';
            app.PMSysVEditField.Editable = 'off';
            app.PMSysVEditField.Value = Inf;
            app.SystemNetVoltageVLabel = uilabel(app.NetworkScannerUIFigure);
            app.SystemNetVoltageVLabel.Text = ' System/Net Voltage (V)';
            app.ScanIntsLabel = uilabel(app.NetworkScannerUIFigure);
            app.ScanIntsLabel.Text = 'Scan Int (s)';
            app.ScanIntervalEditField = uieditfield(app.NetworkScannerUIFigure, 'numeric');
            app.ScanIntervalEditField.ValueDisplayFormat = '%.3f';
            app.ScanIntervalEditField.Editable = 'off';
            app.ScanIntervalEditField.Enable = 'off';
            app.NetPanel = uipanel(app.NetworkScannerUIFigure);
            app.NodeEditField_1 = uieditfield(app.NetPanel, 'numeric');
            app.NodeEditField_1.Limits = [0 127];
            app.NodeEditField_1.ValueChangedFcn = createCallbackFcn(app, @NodeEditField_ValueChanged, true);
            app.NodeEditField_1.Value = 1;
            app.NodeEditField_2 = uieditfield(app.NetPanel, 'numeric');
            app.NodeEditField_2.Limits = [0 127];
            app.NodeEditField_2.ValueChangedFcn = createCallbackFcn(app, @NodeEditField_ValueChanged, true);
            app.NodeEditField_2.Value = 2;
            app.NodeEditField_3 = uieditfield(app.NetPanel, 'numeric');
            app.NodeEditField_3.Limits = [0 127];
            app.NodeEditField_3.ValueChangedFcn = createCallbackFcn(app, @NodeEditField_ValueChanged, true);
            app.NodeEditField_3.Value = 3;
            app.NodeEditField_4 = uieditfield(app.NetPanel, 'numeric');
            app.NodeEditField_4.Limits = [0 127];
            app.NodeEditField_4.ValueChangedFcn = createCallbackFcn(app, @NodeEditField_ValueChanged, true);
            app.NodeEditField_4.Value = 9;
            app.NodeEditField_5 = uieditfield(app.NetPanel, 'numeric');
            app.NodeEditField_5.Limits = [0 127];
            app.NodeEditField_5.ValueChangedFcn = createCallbackFcn(app, @NodeEditField_ValueChanged, true);
            app.NodeEditField_6 = uieditfield(app.NetPanel, 'numeric');
            app.NodeEditField_6.Limits = [0 127];
            app.NodeEditField_6.ValueChangedFcn = createCallbackFcn(app, @NodeEditField_ValueChanged, true);
            app.NodeEditField_7 = uieditfield(app.NetPanel, 'numeric');
            app.NodeEditField_7.Limits = [0 127];
            app.NodeEditField_7.ValueChangedFcn = createCallbackFcn(app, @NodeEditField_ValueChanged, true);
            app.NodeEditField_8 = uieditfield(app.NetPanel, 'numeric');
            app.NodeEditField_8.Limits = [0 127];
            app.NodeEditField_8.ValueChangedFcn = createCallbackFcn(app, @NodeEditField_ValueChanged, true);
            app.NodeEditField_9 = uieditfield(app.NetPanel, 'numeric');
            app.NodeEditField_9.Limits = [0 127];
            app.NodeEditField_9.ValueChangedFcn = createCallbackFcn(app, @NodeEditField_ValueChanged, true);
            app.NodeEditField_10 = uieditfield(app.NetPanel, 'numeric');
            app.NodeEditField_10.Limits = [0 127];
            app.NodeEditField_10.ValueChangedFcn = createCallbackFcn(app, @NodeEditField_ValueChanged, true);
            app.RevEditField_1 = uieditfield(app.NetPanel, 'text');
            app.RevEditField_1.Editable = 'off';
            app.RevEditField_2 = uieditfield(app.NetPanel, 'text');
            app.RevEditField_2.Editable = 'off';
            app.RevEditField_3 = uieditfield(app.NetPanel, 'text');
            app.RevEditField_3.Editable = 'off';
            app.RevEditField_4 = uieditfield(app.NetPanel, 'text');
            app.RevEditField_4.Editable = 'off';
            app.RevEditField_8 = uieditfield(app.NetPanel, 'text');
            app.RevEditField_8.Editable = 'off';
            app.RevEditField_8.Enable = 'off';
            app.RevEditField_7 = uieditfield(app.NetPanel, 'text');
            app.RevEditField_7.Editable = 'off';
            app.RevEditField_6 = uieditfield(app.NetPanel, 'text');
            app.RevEditField_6.Editable = 'off';
            app.RevEditField_5 = uieditfield(app.NetPanel, 'text');
            app.RevEditField_5.Editable = 'off';
            app.RevEditField_10 = uieditfield(app.NetPanel, 'text');
            app.RevEditField_10.Editable = 'off';
            app.RevEditField_10.Enable = 'off';
            app.RevEditField_9 = uieditfield(app.NetPanel, 'text');
            app.RevEditField_9.Editable = 'off';
            app.RevEditField_9.Enable = 'off';
            app.NetErrEditField_1 = uieditfield(app.NetPanel, 'numeric');
            app.NetErrEditField_1.ValueDisplayFormat = '%.0f';
            app.NetErrEditField_1.Editable = 'off';
            app.NetErrEditField_2 = uieditfield(app.NetPanel, 'numeric');
            app.NetErrEditField_2.ValueDisplayFormat = '%.0f';
            app.NetErrEditField_2.Editable = 'off';
            app.NetErrEditField_3 = uieditfield(app.NetPanel, 'numeric');
            app.NetErrEditField_3.ValueDisplayFormat = '%.0f';
            app.NetErrEditField_3.Editable = 'off';
            app.NetErrEditField_4 = uieditfield(app.NetPanel, 'numeric');
            app.NetErrEditField_4.ValueDisplayFormat = '%.0f';
            app.NetErrEditField_4.Editable = 'off';
            app.NetErrEditField_5 = uieditfield(app.NetPanel, 'numeric');
            app.NetErrEditField_5.ValueDisplayFormat = '%.0f';
            app.NetErrEditField_5.Editable = 'off';
            app.NetErrEditField_6 = uieditfield(app.NetPanel, 'numeric');
            app.NetErrEditField_6.ValueDisplayFormat = '%.0f';
            app.NetErrEditField_6.Editable = 'off';
            app.NetErrEditField_7 = uieditfield(app.NetPanel, 'numeric');
            app.NetErrEditField_7.ValueDisplayFormat = '%.0f';
            app.NetErrEditField_7.Editable = 'off';
            app.NetErrEditField_8 = uieditfield(app.NetPanel, 'numeric');
            app.NetErrEditField_8.ValueDisplayFormat = '%.0f';
            app.NetErrEditField_8.Editable = 'off';
            app.NetErrEditField_8.Enable = 'off';
            app.NetErrEditField_9 = uieditfield(app.NetPanel, 'numeric');
            app.NetErrEditField_9.ValueDisplayFormat = '%.0f';
            app.NetErrEditField_9.Editable = 'off';
            app.NetErrEditField_9.Enable = 'off';
            app.NetErrEditField_10 = uieditfield(app.NetPanel, 'numeric');
            app.NetErrEditField_10.ValueDisplayFormat = '%.0f';
            app.NetErrEditField_10.Editable = 'off';
            app.NetErrEditField_10.Enable = 'off';
            app.ExtErrEditField_1 = uieditfield(app.NetPanel, 'numeric');
            app.ExtErrEditField_1.ValueDisplayFormat = '%.0f';
            app.ExtErrEditField_1.Editable = 'off';
            app.ExtErrEditField_2 = uieditfield(app.NetPanel, 'numeric');
            app.ExtErrEditField_2.ValueDisplayFormat = '%.0f';
            app.ExtErrEditField_2.Editable = 'off';
            app.ExtErrEditField_3 = uieditfield(app.NetPanel, 'numeric');
            app.ExtErrEditField_3.ValueDisplayFormat = '%.0f';
            app.ExtErrEditField_3.Editable = 'off';
            app.ExtErrEditField_4 = uieditfield(app.NetPanel, 'numeric');
            app.ExtErrEditField_4.ValueDisplayFormat = '%.0f';
            app.ExtErrEditField_4.Editable = 'off';
            app.ExtErrEditField_5 = uieditfield(app.NetPanel, 'numeric');
            app.ExtErrEditField_5.ValueDisplayFormat = '%.0f';
            app.ExtErrEditField_5.Editable = 'off';
            app.ExtErrEditField_6 = uieditfield(app.NetPanel, 'numeric');
            app.ExtErrEditField_6.ValueDisplayFormat = '%.0f';
            app.ExtErrEditField_6.Editable = 'off';
            app.ExtErrEditField_7 = uieditfield(app.NetPanel, 'numeric');
            app.ExtErrEditField_7.ValueDisplayFormat = '%.0f';
            app.ExtErrEditField_7.Editable = 'off';
            app.ExtErrEditField_8 = uieditfield(app.NetPanel, 'numeric');
            app.ExtErrEditField_8.ValueDisplayFormat = '%.0f';
            app.ExtErrEditField_8.Editable = 'off';
            app.ExtErrEditField_8.Enable = 'off';
            app.ExtErrEditField_9 = uieditfield(app.NetPanel, 'numeric');
            app.ExtErrEditField_9.ValueDisplayFormat = '%.0f';
            app.ExtErrEditField_9.Editable = 'off';
            app.ExtErrEditField_9.Enable = 'off';
            app.ExtErrEditField_10 = uieditfield(app.NetPanel, 'numeric');
            app.ExtErrEditField_10.ValueDisplayFormat = '%.0f';
            app.ExtErrEditField_10.Editable = 'off';
            app.ExtErrEditField_10.Enable = 'off';
            app.VINEditField_1 = uieditfield(app.NetPanel, 'numeric');
            app.VINEditField_1.ValueDisplayFormat = '%.2f';
            app.VINEditField_1.Editable = 'off';
            app.VINEditField_1.Value = Inf;
            app.CANErrEditField_1 = uieditfield(app.NetPanel, 'numeric');
            app.CANErrEditField_1.ValueDisplayFormat = '%.0f';
            app.CANErrEditField_1.Editable = 'off';
            app.CANErrEditField_1.Enable = 'off';
            app.CANErrEditField_1.Value = Inf;
            app.CANErrEditField_2 = uieditfield(app.NetPanel, 'numeric');
            app.CANErrEditField_2.ValueDisplayFormat = '%.0f';
            app.CANErrEditField_2.Editable = 'off';
            app.CANErrEditField_2.Enable = 'off';
            app.CANErrEditField_2.Value = Inf;
            app.VINEditField_2 = uieditfield(app.NetPanel, 'numeric');
            app.VINEditField_2.ValueDisplayFormat = '%.2f';
            app.VINEditField_2.Editable = 'off';
            app.VINEditField_2.Value = Inf;
            app.CANErrEditField_3 = uieditfield(app.NetPanel, 'numeric');
            app.CANErrEditField_3.ValueDisplayFormat = '%.0f';
            app.CANErrEditField_3.Editable = 'off';
            app.CANErrEditField_3.Enable = 'off';
            app.CANErrEditField_3.Value = Inf;
            app.VINEditField_3 = uieditfield(app.NetPanel, 'numeric');
            app.VINEditField_3.ValueDisplayFormat = '%.2f';
            app.VINEditField_3.Editable = 'off';
            app.VINEditField_3.Value = Inf;
            app.CANErrEditField_4 = uieditfield(app.NetPanel, 'numeric');
            app.CANErrEditField_4.ValueDisplayFormat = '%.0f';
            app.CANErrEditField_4.Editable = 'off';
            app.CANErrEditField_4.Enable = 'off';
            app.CANErrEditField_4.Value = Inf;
            app.VINEditField_4 = uieditfield(app.NetPanel, 'numeric');
            app.VINEditField_4.ValueDisplayFormat = '%.2f';
            app.VINEditField_4.Editable = 'off';
            app.VINEditField_4.Value = Inf;
            app.CANErrEditField_5 = uieditfield(app.NetPanel, 'numeric');
            app.CANErrEditField_5.ValueDisplayFormat = '%.0f';
            app.CANErrEditField_5.Editable = 'off';
            app.CANErrEditField_5.Enable = 'off';
            app.CANErrEditField_5.Value = Inf;
            app.VINEditField_5 = uieditfield(app.NetPanel, 'numeric');
            app.VINEditField_5.ValueDisplayFormat = '%.2f';
            app.VINEditField_5.Editable = 'off';
            app.VINEditField_5.Value = Inf;
            app.CANErrEditField_6 = uieditfield(app.NetPanel, 'numeric');
            app.CANErrEditField_6.ValueDisplayFormat = '%.0f';
            app.CANErrEditField_6.Editable = 'off';
            app.CANErrEditField_6.Enable = 'off';
            app.CANErrEditField_6.Value = Inf;
            app.VINEditField_6 = uieditfield(app.NetPanel, 'numeric');
            app.VINEditField_6.ValueDisplayFormat = '%.2f';
            app.VINEditField_6.Editable = 'off';
            app.VINEditField_6.Value = Inf;
            app.CANErrEditField_7 = uieditfield(app.NetPanel, 'numeric');
            app.CANErrEditField_7.ValueDisplayFormat = '%.0f';
            app.CANErrEditField_7.Editable = 'off';
            app.CANErrEditField_7.Enable = 'off';
            app.CANErrEditField_7.Value = Inf;
            app.VINEditField_7 = uieditfield(app.NetPanel, 'numeric');
            app.VINEditField_7.ValueDisplayFormat = '%.2f';
            app.VINEditField_7.Editable = 'off';
            app.VINEditField_7.Value = Inf;
            app.CANErrEditField_8 = uieditfield(app.NetPanel, 'numeric');
            app.CANErrEditField_8.ValueDisplayFormat = '%.0f';
            app.CANErrEditField_8.Editable = 'off';
            app.CANErrEditField_8.Enable = 'off';
            app.CANErrEditField_8.Value = Inf;
            app.VINEditField_8 = uieditfield(app.NetPanel, 'numeric');
            app.VINEditField_8.ValueDisplayFormat = '%.2f';
            app.VINEditField_8.Editable = 'off';
            app.VINEditField_8.Enable = 'off';
            app.VINEditField_8.Value = Inf;
            app.CANErrEditField_9 = uieditfield(app.NetPanel, 'numeric');
            app.CANErrEditField_9.ValueDisplayFormat = '%.0f';
            app.CANErrEditField_9.Editable = 'off';
            app.CANErrEditField_9.Enable = 'off';
            app.CANErrEditField_9.Value = Inf;
            app.VINEditField_9 = uieditfield(app.NetPanel, 'numeric');
            app.VINEditField_9.ValueDisplayFormat = '%.2f';
            app.VINEditField_9.Editable = 'off';
            app.VINEditField_9.Enable = 'off';
            app.VINEditField_9.Value = Inf;
            app.CANErrEditField_10 = uieditfield(app.NetPanel, 'numeric');
            app.CANErrEditField_10.ValueDisplayFormat = '%.0f';
            app.CANErrEditField_10.Editable = 'off';
            app.CANErrEditField_10.Enable = 'off';
            app.CANErrEditField_10.Value = Inf;
            app.VINEditField_10 = uieditfield(app.NetPanel, 'numeric');
            app.VINEditField_10.ValueDisplayFormat = '%.2f';
            app.VINEditField_10.Editable = 'off';
            app.VINEditField_10.Enable = 'off';
            app.VINEditField_10.Value = Inf;
            app.ResetCountersButton = uibutton(app.NetPanel, 'push');
            app.ResetCountersButton.ButtonPushedFcn = createCallbackFcn(app, @ResetCountersButtonPushed, true);
            app.ResetCountersButton.Text = 'Reset Counters';
            app.SWRevLabel = uilabel(app.NetPanel);
            app.SWRevLabel.Text = 'SW Rev';
            app.NetworkErrorsLabel = uilabel(app.NetPanel);
            app.NetworkErrorsLabel.Text = {'Network'; 'Errors'};
            app.ExternalErrorsLabel = uilabel(app.NetPanel);
            app.ExternalErrorsLabel.Text = {'External'; 'Errors'};
            app.VINVCheckBox = uicheckbox(app.NetPanel);
            app.VINVCheckBox.ValueChangedFcn = createCallbackFcn(app, @VINVCheckBoxValueChanged, true);
            app.VINVCheckBox.Text = 'VIN (V)';
            app.VINVCheckBox.Value = true;
            app.CANErrCheckBox = uicheckbox(app.NetPanel);
            app.CANErrCheckBox.ValueChangedFcn = createCallbackFcn(app, @CANErrCheckBoxValueChanged, true);
            app.CANErrCheckBox.Text = 'CANErr';
            app.LastErrEditField_1 = uieditfield(app.NetPanel, 'text');
            app.LastErrEditField_1.Editable = 'off';
            app.LastErrEditField_2 = uieditfield(app.NetPanel, 'text');
            app.LastErrEditField_2.Editable = 'off';
            app.LastErrEditField_3 = uieditfield(app.NetPanel, 'text');
            app.LastErrEditField_3.Editable = 'off';
            app.LastErrEditField_4 = uieditfield(app.NetPanel, 'text');
            app.LastErrEditField_4.Editable = 'off';
            app.LastErrEditField_5 = uieditfield(app.NetPanel, 'text');
            app.LastErrEditField_5.Editable = 'off';
            app.LastErrEditField_6 = uieditfield(app.NetPanel, 'text');
            app.LastErrEditField_6.Editable = 'off';
            app.LastErrEditField_7 = uieditfield(app.NetPanel, 'text');
            app.LastErrEditField_7.Editable = 'off';
            app.LastErrEditField_8 = uieditfield(app.NetPanel, 'text');
            app.LastErrEditField_8.Editable = 'off';
            app.LastErrEditField_8.Enable = 'off';
            app.LastErrEditField_9 = uieditfield(app.NetPanel, 'text');
            app.LastErrEditField_9.Editable = 'off';
            app.LastErrEditField_9.Enable = 'off';
            app.LastErrEditField_10 = uieditfield(app.NetPanel, 'text');
            app.LastErrEditField_10.Editable = 'off';
            app.LastErrEditField_10.Enable = 'off';
            app.ErrorMessageLabel = uilabel(app.NetPanel);
            app.ErrorMessageLabel.Text = 'Error Message';
            app.TempEditField_1 = uieditfield(app.NetPanel, 'numeric');
            app.TempEditField_1.ValueDisplayFormat = '%.1f';
            app.TempEditField_1.Editable = 'off';
            app.TempEditField_1.Enable = 'off';
            app.TempEditField_1.Value = Inf;
            app.TempCheckBox = uicheckbox(app.NetPanel);
            app.TempCheckBox.ValueChangedFcn = createCallbackFcn(app, @TempCheckBoxValueChanged, true);
            app.TempCheckBox.Text = 'Temp (⁰C) ';
            app.TempEditField_2 = uieditfield(app.NetPanel, 'numeric');
            app.TempEditField_2.ValueDisplayFormat = '%.1f';
            app.TempEditField_2.Editable = 'off';
            app.TempEditField_2.Enable = 'off';
            app.TempEditField_2.Value = Inf;
            app.TempEditField_3 = uieditfield(app.NetPanel, 'numeric');
            app.TempEditField_3.ValueDisplayFormat = '%.1f';
            app.TempEditField_3.Editable = 'off';
            app.TempEditField_3.Enable = 'off';
            app.TempEditField_3.Value = Inf;
            app.TempEditField_4 = uieditfield(app.NetPanel, 'numeric');
            app.TempEditField_4.ValueDisplayFormat = '%.1f';
            app.TempEditField_4.Editable = 'off';
            app.TempEditField_4.Enable = 'off';
            app.TempEditField_4.Value = Inf;
            app.TempEditField_5 = uieditfield(app.NetPanel, 'numeric');
            app.TempEditField_5.ValueDisplayFormat = '%.1f';
            app.TempEditField_5.Editable = 'off';
            app.TempEditField_5.Enable = 'off';
            app.TempEditField_5.Value = Inf;
            app.TempEditField_6 = uieditfield(app.NetPanel, 'numeric');
            app.TempEditField_6.ValueDisplayFormat = '%.1f';
            app.TempEditField_6.Editable = 'off';
            app.TempEditField_6.Enable = 'off';
            app.TempEditField_6.Value = Inf;
            app.TempEditField_7 = uieditfield(app.NetPanel, 'numeric');
            app.TempEditField_7.ValueDisplayFormat = '%.1f';
            app.TempEditField_7.Editable = 'off';
            app.TempEditField_7.Enable = 'off';
            app.TempEditField_7.Value = Inf;
            app.TempEditField_8 = uieditfield(app.NetPanel, 'numeric');
            app.TempEditField_8.ValueDisplayFormat = '%.1f';
            app.TempEditField_8.Editable = 'off';
            app.TempEditField_8.Enable = 'off';
            app.TempEditField_8.Value = Inf;
            app.TempEditField_9 = uieditfield(app.NetPanel, 'numeric');
            app.TempEditField_9.ValueDisplayFormat = '%.1f';
            app.TempEditField_9.Editable = 'off';
            app.TempEditField_9.Enable = 'off';
            app.TempEditField_9.Value = Inf;
            app.TempEditField_10 = uieditfield(app.NetPanel, 'numeric');
            app.TempEditField_10.ValueDisplayFormat = '%.1f';
            app.TempEditField_10.Editable = 'off';
            app.TempEditField_10.Enable = 'off';
            app.TempEditField_10.Value = Inf;
            app.AttemptEditField_1 = uieditfield(app.NetPanel, 'numeric');
            app.AttemptEditField_1.ValueDisplayFormat = '%.0f';
            app.AttemptEditField_1.Editable = 'off';
            app.AttemptEditField_2 = uieditfield(app.NetPanel, 'numeric');
            app.AttemptEditField_2.ValueDisplayFormat = '%.0f';
            app.AttemptEditField_2.Editable = 'off';
            app.AttemptEditField_3 = uieditfield(app.NetPanel, 'numeric');
            app.AttemptEditField_3.ValueDisplayFormat = '%.0f';
            app.AttemptEditField_3.Editable = 'off';
            app.AttemptEditField_4 = uieditfield(app.NetPanel, 'numeric');
            app.AttemptEditField_4.ValueDisplayFormat = '%.0f';
            app.AttemptEditField_4.Editable = 'off';
            app.AttemptEditField_5 = uieditfield(app.NetPanel, 'numeric');
            app.AttemptEditField_5.ValueDisplayFormat = '%.0f';
            app.AttemptEditField_5.Editable = 'off';
            app.AttemptEditField_6 = uieditfield(app.NetPanel, 'numeric');
            app.AttemptEditField_6.ValueDisplayFormat = '%.0f';
            app.AttemptEditField_6.Editable = 'off';
            app.AttemptEditField_7 = uieditfield(app.NetPanel, 'numeric');
            app.AttemptEditField_7.ValueDisplayFormat = '%.0f';
            app.AttemptEditField_7.Editable = 'off';
            app.AttemptEditField_8 = uieditfield(app.NetPanel, 'numeric');
            app.AttemptEditField_8.ValueDisplayFormat = '%.0f';
            app.AttemptEditField_8.Editable = 'off';
            app.AttemptEditField_8.Enable = 'off';
            app.AttemptEditField_9 = uieditfield(app.NetPanel, 'numeric');
            app.AttemptEditField_9.ValueDisplayFormat = '%.0f';
            app.AttemptEditField_9.Editable = 'off';
            app.AttemptEditField_9.Enable = 'off';
            app.AttemptEditField_10 = uieditfield(app.NetPanel, 'numeric');
            app.AttemptEditField_10.ValueDisplayFormat = '%.0f';
            app.AttemptEditField_10.Editable = 'off';
            app.AttemptEditField_10.Enable = 'off';
            app.GetStatusButton = uibutton(app.NetworkScannerUIFigure, 'push');
            app.GetStatusButton.ButtonPushedFcn = createCallbackFcn(app, @GetStatusButtonPushed, true);
            app.GetStatusButton.Text = {'Get '; 'Status'};
            app.APRSSIEditFieldLabel = uilabel(app.NetworkScannerUIFigure);
            app.APRSSIEditFieldLabel.Text = 'AP RSSI';
            app.APRSSIEditField = uieditfield(app.NetworkScannerUIFigure, 'numeric');
            app.APRSSIEditField.ValueDisplayFormat = '%.1f';
            app.APRSSIEditField.Editable = 'off';
            app.LogFilenameEditField = uieditfield(app.NetworkScannerUIFigure, 'text');
            app.LogFilenameEditField.Editable = 'off';
            app.FolderButton = uibutton(app.NetworkScannerUIFigure, 'push');
            app.FolderButton.ButtonPushedFcn = createCallbackFcn(app, @FolderButtonPushed, true);
            app.FolderButton.Text = 'Folder';
            app.FolderEditField = uieditfield(app.NetworkScannerUIFigure, 'text');
            app.FolderEditField.Editable = 'off';
            app.FolderEditField.Value = 'C:\Users\rhart\Documents\MATLAB\NNP\Data\G102';
            app.LogFileNameLabel = uilabel(app.NetworkScannerUIFigure);
            app.LogFileNameLabel.Text = 'Log File Name';
            app.UserEditFieldLabel = uilabel(app.NetworkScannerUIFigure);
            app.UserEditFieldLabel.Text = 'User';
            app.UserEditField = uieditfield(app.NetworkScannerUIFigure, 'numeric');
            app.UserEditField.ValueDisplayFormat = '%d';
            app.UserEditField.Value = 102;
            app.LogtofileButton = uibutton(app.NetworkScannerUIFigure, 'state');
            app.LogtofileButton.ValueChangedFcn = createCallbackFcn(app, @LogtofileButtonValueChanged, true);
            app.LogtofileButton.Text = {'Log is'; 'Off'};
            app.NewLogFile = uibutton(app.NetworkScannerUIFigure, 'push');
            app.NewLogFile.ButtonPushedFcn = createCallbackFcn(app, @NewLogFileButtonPushed, true);
            app.NewLogFile.Text = {'New'; 'Log'; 'File'};
            app.FcloseButton = uibutton(app.NetworkScannerUIFigure, 'push');
            app.FcloseButton.ButtonPushedFcn = createCallbackFcn(app, @FcloseButtonPushed, true);
            app.FcloseButton.Text = {'Close'; 'File'};
            app.LowPower = uibutton(app.NetworkScannerUIFigure, 'push');
            app.LowPower.ButtonPushedFcn = createCallbackFcn(app, @LowPowerButtonPushed, true);
            app.LowPower.Text = {'Low '; 'Power'};
            app.Label = uilabel(app.NetworkScannerUIFigure);
            app.Label.Text = 'Append comments to the comments file with the date, time and associated log file appended.';
            app.CommentFileNameEditField = uieditfield(app.NetworkScannerUIFigure, 'text');
            app.CommentFileNameEditField.Editable = 'off';
            app.CommentFileNameLabel = uilabel(app.NetworkScannerUIFigure);
            app.CommentFileNameLabel.Text = 'Comment File Name';
            app.CommentsTextAreaLabel = uilabel(app.NetworkScannerUIFigure);
            app.CommentsTextAreaLabel.Text = 'Comments';
            app.CommentsTextArea = uitextarea(app.NetworkScannerUIFigure);
            app.WriteButton = uibutton(app.NetworkScannerUIFigure, 'push');
            app.WriteButton.ButtonPushedFcn = createCallbackFcn(app, @WriteButtonPushed, true);
            app.WriteButton.Text = 'Write';
            app.PMModeEditFieldLabel = uilabel(app.NetworkScannerUIFigure);
            app.PMModeEditFieldLabel.Text = 'PMmode';
            app.PMModeEditField = uieditfield(app.NetworkScannerUIFigure, 'numeric');
            app.PMModeTextEditField = uieditfield(app.NetworkScannerUIFigure, 'text');
            app.NetStatusEditField = uieditfield(app.NetworkScannerUIFigure, 'numeric');
            app.EnterPatientButton = uibutton(app.NetworkScannerUIFigure, 'push');
            app.EnterPatientButton.ButtonPushedFcn = createCallbackFcn(app, @EnterPatientButtonPushed, true);
            app.EnterPatientButton.Text = {'Enter '; 'Patient'};
            app.TestFeaturesButton = uibutton(app.NetworkScannerUIFigure, 'push');
            app.TestFeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @TestFeaturesButtonPushed, true);
            app.TestFeaturesButton.Tooltip = {'Test Features or ''ProduceX'''};
            app.TestFeaturesButton.Text = {'Test'; 'Features'};
            app.PlotVinCheckBox = uicheckbox(app.NetworkScannerUIFigure);
            app.PlotVinCheckBox.Text = 'Plot Vin';
            app.ResetVinPlotButton = uibutton(app.NetworkScannerUIFigure, 'push');
            app.ResetVinPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ResetVinPlotButtonPushed, true);
            app.ResetVinPlotButton.Text = 'Reset';
            app.highlightDropDownLabel = uilabel(app.NetworkScannerUIFigure);
            app.highlightDropDownLabel.Text = 'highlight';
            app.highlightDropDown = uidropdown(app.NetworkScannerUIFigure);
            app.highlightDropDown.Items = {'1', '2', '3', '4'};
            app.highlightDropDown.ValueChangedFcn = createCallbackFcn(app, @highlightDropDownValueChanged, true);
            app.highlightDropDown.Value = '1';
            app.ResetPMErrCnt_Button = uibutton(app.NetworkScannerUIFigure, 'push');
            app.ResetPMErrCnt_Button.ButtonPushedFcn = createCallbackFcn(app, @ResetPMErrCnt_ButtonPushed, true);
            app.ResetPMErrCnt_Button.Text = 'Reset';
            % Show the figure after all components are created
            app.NetworkScannerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = netscannerg(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NetworkScannerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NetworkScannerUIFigure)
        end
    end
end