classdef nnpclinicalsuite < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SurgerySuiteUIFigure       matlab.ui.Figure
        ConfigAPButton             matlab.ui.control.Button
        NetworkScannerButton       matlab.ui.control.Button
        ProfilerButton             matlab.ui.control.Button
        BTTestButton               matlab.ui.control.Button
        NNPAccessPointButton       matlab.ui.control.StateButton
        CommChecklistButton        matlab.ui.control.Button
        PMBootloaderButton         matlab.ui.control.Button
        RMBootloaderButton         matlab.ui.control.Button
        Commands                   matlab.ui.container.TabGroup
        ScriptsTab                 matlab.ui.container.Tab
        DisableScriptsButton       matlab.ui.control.Button
        EnableScriptsButton        matlab.ui.control.Button
        StartupScriptButton        matlab.ui.control.Button
        ScriptEdit                 matlab.ui.control.Button
        PowerTab                   matlab.ui.container.Tab
        PMShutdownButton           matlab.ui.control.Button
        PMVersionButton            matlab.ui.control.Button
        EMGTab                     matlab.ui.container.Tab
        RawEMGRecordingButton      matlab.ui.control.Button
        DisplayRawEMGButton        matlab.ui.control.Button
        DisplayProcessedButton     matlab.ui.control.Button
        RecordProcessedButton      matlab.ui.control.Button
        ConfigurationsTab          matlab.ui.container.Tab
        ReadRestoreUserDataButton  matlab.ui.control.Button
        RemoteModuleStatusButton   matlab.ui.control.Button
        PDOSetupButton             matlab.ui.control.Button
        BackupRestoreMemoryButton  matlab.ui.control.Button
        PMDateTime                 matlab.ui.control.Button
        Patterns                   matlab.ui.container.Tab
        MarkerSet                  matlab.ui.control.Button
        PatternEdit                matlab.ui.control.Button
        MessagesTextAreaLabel      matlab.ui.control.Label
        MessagesTextArea           matlab.ui.control.TextArea
        ODTestButton               matlab.ui.control.Button
        NotesButton                matlab.ui.control.Button
        DataLogButton              matlab.ui.control.Button
        ResetUSBButton             matlab.ui.control.Button
    end

    % Notes
    % In this clicnal suite, I want to add tools that I would use to set
    % various parameters, based upon recorded EMG values, and that sort of
    % thing.
    
    properties (Access = private)
    end
    
    properties (Access = public)
        nnp = []; % Description
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.NNPAccessPointButton.Value = 0;
%             app.NNPAccessPointButtonValueChanged();
        end

        % Button pushed function: ConfigAPButton
        function ConfigAPButtonPushed(app, event)
            radioconfig(app.nnp);
        end

        % Callback function
        function NNPAccessPointButtonPushed(app, event)
            if ~isempty(app.nnp)
                delete(app.nnp)
            end
            
            if app.NNPAccessPointButton.Value
                app.NNPAccessPointButton.Text = 'NNP Access Point-Select Port';
                while true
                    app.nnp = NNPAPI();
                    if isempty(app.nnp.port)
                        resp = questdlg('No port chosen for NNP Access Point.  Plug in AccessPoint and try again.  If you do not want to try again, press No and the application will close');
                        if isequal(resp, 'No')
                            close(app.SurgerySuiteUIFigure);
                            return;
                        end
                    else
                        break;
                    end
                end
                app.NNPAccessPointButton.Text = 'NNP Access Point Open';
            else
                app.NNPAccessPointButton.Text = 'NNP Access Point Closed';
            end
            
        end

        % Button pushed function: BTTestButton
        function BTTestButtonPushed(app, event)
            bptestf(app.nnp);
        end

        % Button pushed function: NetworkScannerButton
        function NetworkScannerButtonPushed(app, event)
            netscannerf(app.nnp);
        end

        % Close request function: SurgerySuiteUIFigure
        function SurgerySuiteUIFigureCloseRequest(app, event)
            if ~isempty(app.nnp)
                delete(app.nnp)
            end
            delete(app)
        end

        % Value changed function: NNPAccessPointButton
        function NNPAccessPointButtonValueChanged(app, event)
            if ~isempty(app.nnp)
                delete(app.nnp)
            end
            
            if app.NNPAccessPointButton.Value
                app.NNPAccessPointButton.Text = 'NNP Access Point-Select Port';
                while true
                    app.nnp = NNPAPI();
                    if isempty(app.nnp.port)
                        resp = questdlg('No port chosen for NNP Access Point.  Plug in AccessPoint and try again.  If you do not want to try again, press No and the application will close');
                        if isequal(resp, 'No')
                            close(app.NNPUtilitiesUIFigure);
                            return;
                        end
                    else
                        break;
                    end
                end
                app.NNPAccessPointButton.Text = 'NNP Access Point Open';
            else
                app.NNPAccessPointButton.Text = 'NNP Access Point Closed';
            end
        end

        % Button pushed function: CommChecklistButton
        function CommChecklistButtonPushed(app, event)
            commchecklist();
        end

        % Button pushed function: ProfilerButton
        function ProfilerButtonPushed(app, event)
            profiler(app.nnp);
        end

        % Button pushed function: PMBootloaderButton
        function PMBootloaderButtonPushed(app, event)
            pmbootloader(app.nnp);
        end

        % Button pushed function: RMBootloaderButton
        function RMBootloaderButtonPushed(app, event)
            rmbootloader(app.nnp);
        end

        % Button pushed function: DisableScriptsButton
        function DisableScriptsButtonPushed(app, event)
            app.nnp.enterWaiting();
            response=app.nnp.nmt(7, '8F'); %disable scripts
            if response==hex2dec('8F')
                dmessage='Scripts Disabled';
                tbc=[1,1,1];
            else
                dmessage=app.nnp.lastError;
                tbc=[1,1,.7];
            end
            app.MessagesTextArea.Value=dmessage;
            app.MessagesTextArea.BackgroundColor=tbc;
        end

        % Button pushed function: EnableScriptsButton
        function EnableScriptsButtonPushed(app, event)
            app.nnp.enterWaiting();
            response=app.nnp.nmt(7, '8E'); %enable scripts
            if response==hex2dec('8E')
                dmessage='Scripts Enabled';
                tbc=[1,1,1];
            else
                dmessage=app.nnp.lastError;
                tbc=[1,1,.7];
            end
            app.MessagesTextArea.Value=dmessage;
            app.MessagesTextArea.BackgroundColor=tbc;
        end

        % Button pushed function: StartupScriptButton
        function StartupScriptButtonPushed(app, event)
            response=app.nnp.nmt(7, '8E'); %enable scripts
            if response==hex2dec('8E')
                dmessage='Scripts Enabled';
                response=app.nnp.nmt(7,'B3',7); % Run startup script
                if response==hex2dec('B3')
                    dmessage='Scripts enabled and Startup Ran';
                    tbc=[1,1,1];
                else
                    dmessage=['Scripts Enabled, but ',app.nnp.lastError];
                    tbc=[1,1,.7];
                end
            else
                dmessage=app.nnp.lastError;
            end
            app.MessagesTextArea.Value=dmessage;
            app.MessagesTextArea.BackgroundColor=tbc;
        end

        % Button pushed function: PMShutdownButton
        function PMShutdownButtonPushed(app, event)
            app.nnp.enterWaiting();
            % Add confirmation dialog here
            resp = questdlg('Do you want to power down the Power Module? You will need to use control tower to restart!');
            if isequal(resp, 'Yes')
                response=app.nnp.nmt(7, '9E'); %enable scripts
                if response==hex2dec('9E')
                    dmessage='PM Shutdown';
                    tbc=[1,1,1];
                else
                    dmessage=app.nnp.lastError;
                    tbc=[1,1,.7];
                end
            else
                dmessage='Cancelled';
                tbc=[1,1,1];
            end
            app.MessagesTextArea.Value=dmessage;
            app.MessagesTextArea.BackgroundColor=tbc;
        end

        % Button pushed function: PMVersionButton
        function PMVersionButtonPushed(app, event)
            resp=double(app.nnp.read(7,'1018',3,'uint32')); % read revision number to test communications
            if ~isempty(resp) % if the return is not empty, give revision number
                dmessage=sprintf('PM version %d',resp);
                tbc=[1,1,1];
            else
                dmessage=app.nnp.lastError;
                tbc=[1,1,.7];
            end
            app.MessagesTextArea.Value=dmessage;
            app.MessagesTextArea.BackgroundColor=tbc;
        end

        % Button pushed function: ODTestButton
        function ODTestButtonPushed(app, event)
            odtest(app.nnp);
        end

        % Button pushed function: ReadRestoreUserDataButton
        function ReadRestoreUserDataButtonPushed(app, event)
            ReadRestoreUserDataR3(app.nnp);
        end

        % Button pushed function: RawEMGRecordingButton
        function RawEMGRecordingButtonPushed(app, event)
            MESRecording(app.nnp);
        end

        % Button pushed function: NotesButton
        function NotesButtonPushed(app, event)
            edit clinicalsuitenotes.txt;
        end

        % Button pushed function: DataLogButton
        function DataLogButtonPushed(app, event)
            PM_Restore_and_Log(app.nnp);
        end

        % Button pushed function: RemoteModuleStatusButton
        function RemoteModuleStatusButtonPushed(app, event)
            RemoteModuleParametersStatus(app.nnp);
        end

        % Button pushed function: DisplayRawEMGButton
        function DisplayRawEMGButtonPushed(app, event)
            DisplayRawEMG(); % This has an additional input for filename or folder
        end

        % Button pushed function: DisplayProcessedButton
        function DisplayProcessedButtonPushed(app, event)
            DisplayProcessedEMG();
        end

        % Button pushed function: PDOSetupButton
        function PDOSetupButtonPushed(app, event)
            PDOcfg(app.nnp);
        end

        % Button pushed function: BackupRestoreMemoryButton
        function BackupRestoreMemoryButtonPushed(app, event)
            Backup_and_Restore_Memory(app.nnp);
        end

        % Button pushed function: MarkerSet
        function MarkerSetButtonPushed(app, event)
            DisplayXMLMarkerSet();
        end

        % Button pushed function: PatternEdit
        function PatternEditButtonPushed(app, event)
            patternedit(app.nnp);
        end

        % Button pushed function: PMDateTime
        function PMDateTimeButtonPushed(app, event)
            resp=setPMDateTime(app.nnp);
            if resp==1
                dmessage='Error setting PM RTC';
            else
                dmessage='PM RTC Set';
            end
            app.MessagesTextArea.Value=dmessage;
        end

        % Button pushed function: ScriptEdit
        function ScriptEditButtonPushed(app, event)
           scriptedit(app.nnp); 
        end

        % Button pushed function: RecordProcessedButton
        function RecordProcessedButtonPushed(app, event)
            RecordProcessedEMG(app.nnp);
        end

        % Button pushed function: ResetUSBButton
        function ResetUSBButtonPushed(app, event)
            app.nnp.refresh();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SurgerySuiteUIFigure = uifigure('Visible', 'off');
            app.SurgerySuiteUIFigure.Name = 'NNP Clinical Suite';
            app.SurgerySuiteUIFigure.CloseRequestFcn = createCallbackFcn(app, @SurgerySuiteUIFigureCloseRequest, true);
            app.ConfigAPButton = uibutton(app.SurgerySuiteUIFigure, 'push');
            app.ConfigAPButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigAPButtonPushed, true);
            app.ConfigAPButton.Text = 'Radio Configuration';
            app.NetworkScannerButton = uibutton(app.SurgerySuiteUIFigure, 'push');
            app.NetworkScannerButton.ButtonPushedFcn = createCallbackFcn(app, @NetworkScannerButtonPushed, true);
            app.NetworkScannerButton.Text = {'Network'; 'Scanner'};
            app.ProfilerButton = uibutton(app.SurgerySuiteUIFigure, 'push');
            app.ProfilerButton.ButtonPushedFcn = createCallbackFcn(app, @ProfilerButtonPushed, true);
            app.ProfilerButton.Text = 'Profiler';
            app.BTTestButton = uibutton(app.SurgerySuiteUIFigure, 'push');
            app.BTTestButton.ButtonPushedFcn = createCallbackFcn(app, @BTTestButtonPushed, true);
            app.BTTestButton.Text = 'BP Test';
            app.NNPAccessPointButton = uibutton(app.SurgerySuiteUIFigure, 'state');
            app.NNPAccessPointButton.ValueChangedFcn = createCallbackFcn(app, @NNPAccessPointButtonValueChanged, true);
            app.NNPAccessPointButton.Text = 'NNP Access Point Open';
            app.CommChecklistButton = uibutton(app.SurgerySuiteUIFigure, 'push');
            app.CommChecklistButton.ButtonPushedFcn = createCallbackFcn(app, @CommChecklistButtonPushed, true);
            app.CommChecklistButton.Text = {'Communications'; 'Checklist'};
            app.PMBootloaderButton = uibutton(app.SurgerySuiteUIFigure, 'push');
            app.PMBootloaderButton.ButtonPushedFcn = createCallbackFcn(app, @PMBootloaderButtonPushed, true);
            app.PMBootloaderButton.Text = {'PM'; 'Bootloader'};
            app.RMBootloaderButton = uibutton(app.SurgerySuiteUIFigure, 'push');
            app.RMBootloaderButton.ButtonPushedFcn = createCallbackFcn(app, @RMBootloaderButtonPushed, true);
            app.RMBootloaderButton.Text = {'RM'; 'Bootloader'};
            app.Commands = uitabgroup(app.SurgerySuiteUIFigure);
            app.ScriptsTab = uitab(app.Commands);
            app.ScriptsTab.Title = 'Scripts';
            app.DisableScriptsButton = uibutton(app.ScriptsTab, 'push');
            app.DisableScriptsButton.ButtonPushedFcn = createCallbackFcn(app, @DisableScriptsButtonPushed, true);
            app.DisableScriptsButton.Text = {'Disable '; 'PM Scripts'};
            app.EnableScriptsButton = uibutton(app.ScriptsTab, 'push');
            app.EnableScriptsButton.ButtonPushedFcn = createCallbackFcn(app, @EnableScriptsButtonPushed, true);
            app.EnableScriptsButton.Text = {'Enable '; 'PM Scripts'};
            app.StartupScriptButton = uibutton(app.ScriptsTab, 'push');
            app.StartupScriptButton.ButtonPushedFcn = createCallbackFcn(app, @StartupScriptButtonPushed, true);
            app.StartupScriptButton.Text = {'Run Startup'; 'Script'};
            app.ScriptEdit = uibutton(app.ScriptsTab, 'push');
            app.ScriptEdit.ButtonPushedFcn = createCallbackFcn(app, @ScriptEditButtonPushed, true);
            app.ScriptEdit.Text = {'Edit'; 'Scripts'};
            app.PowerTab = uitab(app.Commands);
            app.PowerTab.Title = 'Power';
            app.PMShutdownButton = uibutton(app.PowerTab, 'push');
            app.PMShutdownButton.ButtonPushedFcn = createCallbackFcn(app, @PMShutdownButtonPushed, true);
            app.PMShutdownButton.Text = {'PM'; 'Shutdown'};
            app.PMVersionButton = uibutton(app.PowerTab, 'push');
            app.PMVersionButton.ButtonPushedFcn = createCallbackFcn(app, @PMVersionButtonPushed, true);
            app.PMVersionButton.Text = {'PM'; 'Version'};
            app.EMGTab = uitab(app.Commands);
            app.EMGTab.Title = 'EMG';
            app.RawEMGRecordingButton = uibutton(app.EMGTab, 'push');
            app.RawEMGRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @RawEMGRecordingButtonPushed, true);
            app.RawEMGRecordingButton.Text = {'Raw EMG'; 'Recording'};
            app.DisplayRawEMGButton = uibutton(app.EMGTab, 'push');
            app.DisplayRawEMGButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayRawEMGButtonPushed, true);
            app.DisplayRawEMGButton.Text = {'Display'; 'Raw'};
            app.DisplayProcessedButton = uibutton(app.EMGTab, 'push');
            app.DisplayProcessedButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayProcessedButtonPushed, true);
            app.DisplayProcessedButton.Text = {'Display'; 'Processed'};
            app.RecordProcessedButton = uibutton(app.EMGTab, 'push');
            app.RecordProcessedButton.ButtonPushedFcn = createCallbackFcn(app, @RecordProcessedButtonPushed, true);
            app.RecordProcessedButton.Text = {'Record'; 'Processed'};
            app.ConfigurationsTab = uitab(app.Commands);
            app.ConfigurationsTab.Title = 'Config';
            app.ReadRestoreUserDataButton = uibutton(app.ConfigurationsTab, 'push');
            app.ReadRestoreUserDataButton.ButtonPushedFcn = createCallbackFcn(app, @ReadRestoreUserDataButtonPushed, true);
            app.ReadRestoreUserDataButton.Text = {'Read'; 'Restore File'};
            app.RemoteModuleStatusButton = uibutton(app.ConfigurationsTab, 'push');
            app.RemoteModuleStatusButton.ButtonPushedFcn = createCallbackFcn(app, @RemoteModuleStatusButtonPushed, true);
            app.RemoteModuleStatusButton.Text = {'Remote Module'; 'Current '; 'Configuration'};
            app.PDOSetupButton = uibutton(app.ConfigurationsTab, 'push');
            app.PDOSetupButton.ButtonPushedFcn = createCallbackFcn(app, @PDOSetupButtonPushed, true);
            app.PDOSetupButton.Text = {'PDO'; 'Setup'};
            app.BackupRestoreMemoryButton = uibutton(app.ConfigurationsTab, 'push');
            app.BackupRestoreMemoryButton.ButtonPushedFcn = createCallbackFcn(app, @BackupRestoreMemoryButtonPushed, true);
            app.BackupRestoreMemoryButton.Text = {'Backup and'; 'Restore Memory'};
            app.PMDateTime = uibutton(app.ConfigurationsTab, 'push');
            app.PMDateTime.ButtonPushedFcn = createCallbackFcn(app, @PMDateTimeButtonPushed, true);
            app.PMDateTime.Text = {'Set PM'; 'Date Time'};
            app.Patterns = uitab(app.Commands);
            app.Patterns.Title = 'Patterns';
            app.MarkerSet = uibutton(app.Patterns, 'push');
            app.MarkerSet.ButtonPushedFcn = createCallbackFcn(app, @MarkerSetButtonPushed, true);
            app.MarkerSet.Text = {'Marker'; 'Set'};
            app.PatternEdit = uibutton(app.Patterns, 'push');
            app.PatternEdit.ButtonPushedFcn = createCallbackFcn(app, @PatternEditButtonPushed, true);
            app.PatternEdit.Text = {'Pattern'; 'Edit'};
            app.MessagesTextAreaLabel = uilabel(app.SurgerySuiteUIFigure);
            app.MessagesTextAreaLabel.Text = 'Messages';
            app.MessagesTextArea = uitextarea(app.SurgerySuiteUIFigure);
            app.ODTestButton = uibutton(app.SurgerySuiteUIFigure, 'push');
            app.ODTestButton.ButtonPushedFcn = createCallbackFcn(app, @ODTestButtonPushed, true);
            app.ODTestButton.Text = 'OD Test';
            app.NotesButton = uibutton(app.SurgerySuiteUIFigure, 'push');
            app.NotesButton.ButtonPushedFcn = createCallbackFcn(app, @NotesButtonPushed, true);
            app.NotesButton.Text = 'Notes';
            app.DataLogButton = uibutton(app.SurgerySuiteUIFigure, 'push');
            app.DataLogButton.ButtonPushedFcn = createCallbackFcn(app, @DataLogButtonPushed, true);
            app.DataLogButton.Text = 'Data Log';
            app.ResetUSBButton = uibutton(app.SurgerySuiteUIFigure, 'push');
            app.ResetUSBButton.ButtonPushedFcn = createCallbackFcn(app, @ResetUSBButtonPushed, true);
            app.ResetUSBButton.Tooltip = {'If USB stops responding reset without need to close nnpapi'};
            app.ResetUSBButton.Text = {'Reset'; 'USB'};
            % Show the figure after all components are created
            app.SurgerySuiteUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nnpclinicalsuite

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SurgerySuiteUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SurgerySuiteUIFigure)
        end
    end
end