classdef nnpsurgerysuite < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SurgerySuiteUIFigure       matlab.ui.Figure
        PMVersionButton_2          matlab.ui.control.Button
        ResetUSBButton             matlab.ui.control.Button
        ClearMessageButton         matlab.ui.control.Button
        ODTestButton               matlab.ui.control.Button
        MessagesTextArea           matlab.ui.control.TextArea
        MessagesTextAreaLabel      matlab.ui.control.Label
        Commands                   matlab.ui.container.TabGroup
        ScriptsTab                 matlab.ui.container.Tab
        StartupScriptButton        matlab.ui.control.Button
        EnableScriptsButton        matlab.ui.control.Button
        DisableScriptsButton       matlab.ui.control.Button
        PMTab                      matlab.ui.container.Tab
        ReadRestoreUserDataButton  matlab.ui.control.Button
        setPMDateTime              matlab.ui.control.Button
        getPMDateTime              matlab.ui.control.Button
        PMVersionButton            matlab.ui.control.Button
        PMShutdownButton           matlab.ui.control.Button
        RemoteTab                  matlab.ui.container.Tab
        PDOSetupButton             matlab.ui.control.Button
        RemoteModuleSetupButton    matlab.ui.control.Button
        EMGTab                     matlab.ui.container.Tab
        EMGRecordingButton         matlab.ui.control.Button
        RMBootloaderButton         matlab.ui.control.Button
        PMBootloaderButton         matlab.ui.control.Button
        CommChecklistButton        matlab.ui.control.Button
        NNPAccessPointButton       matlab.ui.control.StateButton
        BTTestButton               matlab.ui.control.Button
        ProfilerButton             matlab.ui.control.Button
        NetworkScannerButton       matlab.ui.control.Button
        ConfigAPButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
         nnp = []; % Description
    end
    
    methods (Access = private)
        
    end
    
    methods (Access = public)
        
        function returnFocus(app)
           % return the focus to the current figure
           figure(app.SurgerySuiteUIFigure); 
        end
        
        function TopMessage(app,message)
             app.MessagesTextArea.Value=vertcat(message, string(app.MessagesTextArea.Value)); % put new message on top
        end
        
        
        function ClearMessages(app)
            app.MessagesTextArea.Value=''; % clear messages
        end
        
        function ResponseMessage(app,good,message)
            if good
                dmessage=message;
                tbc=[1,1,1];
            else
                dmessage=app.nnp.lastError;
                tbc=[1,1,.7];
            end
            app.MessagesTextArea.Value=dmessage;
            app.MessagesTextArea.BackgroundColor=tbc;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.NNPAccessPointButton.Value = 0;
            %app.NNPAccessPointButtonValueChanged();
        end

        % Button pushed function: ConfigAPButton
        function ConfigAPButtonPushed(app, event)
            radioconfig(app.nnp,app.SurgerySuiteUIFigure);
        end

        % Callback function
        function NNPAccessPointButtonPushed(app, event)
                        if ~isempty(app.nnp) 
                 delete(app.nnp)
            end
            
            if app.NNPAccessPointButton.Value 
                app.NNPAccessPointButton.Text = 'NNP Access Point-Select Port';
                 while true
                    app.nnp = NNPAPI();
                    if isempty(app.nnp.port)
                        resp = questdlg('No port chosen for NNP Access Point.  Plug in AccessPoint and try again.  If you do not want to try again, press No and the application will close');
                        if isequal(resp, 'No')
                            close(app.SurgerySuiteUIFigure);
                            return;
                        end
                    else
                        break;
                    end
                 end
                 app.NNPAccessPointButton.Text = 'NNP Access Point Open';
            else
                app.NNPAccessPointButton.Text = 'NNP Access Point Closed';
             end
            
        end

        % Button pushed function: BTTestButton
        function BTTestButtonPushed(app, event)
             bptestf(app.nnp,app.SurgerySuiteUIFigure);
        end

        % Button pushed function: NetworkScannerButton
        function NetworkScannerButtonPushed(app, event)
              netscannerf(app.nnp,app.SurgerySuiteUIFigure);
        end

        % Close request function: SurgerySuiteUIFigure
        function SurgerySuiteUIFigureCloseRequest(app, event)
            if ~isempty(app.nnp)
                delete(app.nnp)
            end
            delete(app)
        end

        % Value changed function: NNPAccessPointButton
        function NNPAccessPointButtonValueChanged(app, event)
            if ~isempty(app.nnp)
                delete(app.nnp)
            end
            
            if app.NNPAccessPointButton.Value
                app.NNPAccessPointButton.Text = 'NNP Access Point-Select Port';
                app.NNPAccessPointButton.BackgroundColor=[.9,.9,.9];
                while true
                    app.TopMessage('Opening...');
                    app.nnp = NNPAPI();
                    app.returnFocus(); 
                    if isempty(app.nnp.port)
                        resp = questdlg('No port chosen for NNP Access Point.  Plug in AccessPoint and try again.  If you do not want to try again, press No and the application will close');
                        if isequal(resp, 'No')
%                             close(app.fillintheblank); % if I want to close something
                            return;
                        end
                    else                        
                        break;
                    end
                end
                app.nnp.timeout=1;  % set new default timeout that matches 5 retries
                app.NNPAccessPointButton.Text = 'NNP Access Point Open';
                app.returnFocus();    
                app.NNPAccessPointButton.BackgroundColor=[0.81,1.00,0.67];
                app.TopMessage('Finished opening Access Point');
            else
                app.NNPAccessPointButton.Text = 'NNP Access Point Closed';
                app.returnFocus();
                app.NNPAccessPointButton.BackgroundColor=[.96,.96,.96];
                app.TopMessage('Finished Closing Access Point');
            end 
        end

        % Button pushed function: CommChecklistButton
        function CommChecklistButtonPushed(app, event)
            commchecklist();
        end

        % Button pushed function: ProfilerButton
        function ProfilerButtonPushed(app, event)
            chanProfiler(app.nnp,app.SurgerySuiteUIFigure);
        end

        % Button pushed function: PMBootloaderButton
        function PMBootloaderButtonPushed(app, event)
            pmbootloader(app.nnp,app.SurgerySuiteUIFigure);
        end

        % Button pushed function: RMBootloaderButton
        function RMBootloaderButtonPushed(app, event)
            rmbootloader(app.nnp,app.SurgerySuiteUIFigure);
        end

        % Button pushed function: DisableScriptsButton
        function DisableScriptsButtonPushed(app, event)
            app.nnp.enterWaiting();
            response=app.nnp.nmt(7, '8F'); %disable scripts
            if response==hex2dec('8F')
                dmessage='Scripts Disabled';
                tbc=[1,1,1];
            else
                dmessage=app.nnp.lastError;
                tbc=[1,1,.7];
            end
            app.MessagesTextArea.Value=dmessage;
            app.MessagesTextArea.BackgroundColor=tbc;
        end

        % Button pushed function: EnableScriptsButton
        function EnableScriptsButtonPushed(app, event)
            app.nnp.enterWaiting();
            response=app.nnp.nmt(7, '8E'); %enable scripts
            if response==hex2dec('8E')
                dmessage='Scripts Enabled';
                tbc=[1,1,1];
            else
               dmessage=app.nnp.lastError;
               tbc=[1,1,.7];
            end
            app.MessagesTextArea.Value=dmessage; 
            app.MessagesTextArea.BackgroundColor=tbc;
        end

        % Button pushed function: StartupScriptButton
        function StartupScriptButtonPushed(app, event)
            response=app.nnp.nmt(7, '8E'); %enable scripts
           if response==hex2dec('8E')
                dmessage='Scripts Enabled';
                response=app.nnp.nmt(7,'B3',7); % Run startup script  
                if response==hex2dec('B3')
                dmessage='Scripts enabled and Startup Ran';
                tbc=[1,1,1];
                else
                dmessage=['Scripts Enabled, but ',app.nnp.lastError];
                tbc=[1,1,.7];
                end
            else
               dmessage=app.nnp.lastError;
            end
            app.MessagesTextArea.Value=dmessage;
            app.MessagesTextArea.BackgroundColor=tbc;
        end

        % Button pushed function: PMShutdownButton
        function PMShutdownButtonPushed(app, event)
            app.nnp.enterWaiting();
            % Add confirmation dialog here
            resp = questdlg('Do you want to power down the Power Module? You will need to use control tower to restart!');
            if isequal(resp, 'Yes')      
                response=app.nnp.nmt(7, '9E'); %enable scripts
                if response==hex2dec('9E')
                    dmessage='PM Shutdown';
                    tbc=[1,1,1];
                else
                    dmessage=app.nnp.lastError;
                    tbc=[1,1,.7];
                end
            else
                dmessage='Cancelled';
                tbc=[1,1,1];
            end
            app.MessagesTextArea.Value=dmessage;
            app.MessagesTextArea.BackgroundColor=tbc;
        end

        % Button pushed function: PMVersionButton
        function PMVersionButtonPushed(app, event)
            resp=double(app.nnp.read(7,'1018',3,'uint32')); % read revision number to test communications
            if ~isempty(resp) % if the return is not empty, give revision number
                dmessage=sprintf('PM version %d',resp);
                tbc=[1,1,1];
            else
               dmessage=app.nnp.lastError;
               tbc=[1,1,.7]; 
            end
            app.MessagesTextArea.Value=dmessage;
            app.MessagesTextArea.BackgroundColor=tbc;
        end

        % Button pushed function: ODTestButton
        function ODTestButtonPushed(app, event)
            odtest(app.nnp,app.SurgerySuiteUIFigure);
        end

        % Button pushed function: ReadRestoreUserDataButton
        function ReadRestoreUserDataButtonPushed(app, event)
            pmRestoreFileDisplay(app.nnp,app.SurgerySuiteUIFigure);
        end

        % Button pushed function: EMGRecordingButton
        function EMGRecordingButtonPushed(app, event)
           emgRecordRaw(app.nnp,app.SurgerySuiteUIFigure);
            %MESRecording(app.nnp); 
        end

        % Button pushed function: ResetUSBButton
        function ResetUSBButtonPushed(app, event)
            app.nnp.refresh();
        end

        % Button pushed function: PMVersionButton_2
        function PMVersionButton_2Pushed(app, event)
            resp=double(app.nnp.read(7,'1018',3,'uint32')); % read revision number to test communications
            app.ResponseMessage(~isempty(resp),sprintf('PM version %d',resp));
        end

        % Button pushed function: RemoteModuleSetupButton
        function RemoteModuleSetupButtonPushed(app, event)
         rmSetup(app.nnp,app.SurgerySuiteUIFigure);    
        end

        % Button pushed function: PDOSetupButton
        function PDOSetupButtonPushed(app, event)
         pdoConfig(app.nnp,app.SurgerySuiteUIFigure);   
        end

        % Button pushed function: getPMDateTime
        function getPMDateTimeButtonPushed(app, event)
         resp=pmDateTimeGet(app.nnp);
            if ischar(resp)
                dmessage=resp;
%                dmessage='test';
            else
                dmessage='Error reading data/time from PM';
            end
            app.MessagesTextArea.Value=dmessage;  
        end

        % Button pushed function: setPMDateTime
        function setPMDateTimeButtonPushed(app, event)
           resp=pmDateTimeSet(app.nnp);
            if resp==1
                dmessage='Error setting PM RTC';
            else
                dmessage='PM RTC Set';
            end
            app.MessagesTextArea.Value=dmessage; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SurgerySuiteUIFigure = uifigure('Visible', 'off');
            app.SurgerySuiteUIFigure.Name = 'NNP Surgery Suite';
            app.SurgerySuiteUIFigure.CloseRequestFcn = createCallbackFcn(app, @SurgerySuiteUIFigureCloseRequest, true);
            app.ConfigAPButton = uibutton(app.SurgerySuiteUIFigure, 'push');
            app.ConfigAPButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigAPButtonPushed, true);
            app.ConfigAPButton.Text = 'Radio Configuration';
            app.NetworkScannerButton = uibutton(app.SurgerySuiteUIFigure, 'push');
            app.NetworkScannerButton.ButtonPushedFcn = createCallbackFcn(app, @NetworkScannerButtonPushed, true);
            app.NetworkScannerButton.Text = {'Network'; 'Scanner'};
            app.ProfilerButton = uibutton(app.SurgerySuiteUIFigure, 'push');
            app.ProfilerButton.ButtonPushedFcn = createCallbackFcn(app, @ProfilerButtonPushed, true);
            app.ProfilerButton.Text = 'Profiler';
            app.BTTestButton = uibutton(app.SurgerySuiteUIFigure, 'push');
            app.BTTestButton.ButtonPushedFcn = createCallbackFcn(app, @BTTestButtonPushed, true);
            app.BTTestButton.Text = 'BP Test';
            app.NNPAccessPointButton = uibutton(app.SurgerySuiteUIFigure, 'state');
            app.NNPAccessPointButton.ValueChangedFcn = createCallbackFcn(app, @NNPAccessPointButtonValueChanged, true);
            app.NNPAccessPointButton.Text = 'NNP Access Point Open';
            app.NNPAccessPointButton.Value = true;
            app.CommChecklistButton = uibutton(app.SurgerySuiteUIFigure, 'push');
            app.CommChecklistButton.ButtonPushedFcn = createCallbackFcn(app, @CommChecklistButtonPushed, true);
            app.CommChecklistButton.Text = {'Communications'; 'Checklist'};
            app.PMBootloaderButton = uibutton(app.SurgerySuiteUIFigure, 'push');
            app.PMBootloaderButton.ButtonPushedFcn = createCallbackFcn(app, @PMBootloaderButtonPushed, true);
            app.PMBootloaderButton.Text = {'PM'; 'Bootloader'};
            app.RMBootloaderButton = uibutton(app.SurgerySuiteUIFigure, 'push');
            app.RMBootloaderButton.ButtonPushedFcn = createCallbackFcn(app, @RMBootloaderButtonPushed, true);
            app.RMBootloaderButton.Text = {'RM'; 'Bootloader'};
            app.Commands = uitabgroup(app.SurgerySuiteUIFigure);
            app.ScriptsTab = uitab(app.Commands);
            app.ScriptsTab.Title = 'Scripts';
            app.DisableScriptsButton = uibutton(app.ScriptsTab, 'push');
            app.DisableScriptsButton.ButtonPushedFcn = createCallbackFcn(app, @DisableScriptsButtonPushed, true);
            app.DisableScriptsButton.Text = {'Disable '; 'PM Scripts'};
            app.EnableScriptsButton = uibutton(app.ScriptsTab, 'push');
            app.EnableScriptsButton.ButtonPushedFcn = createCallbackFcn(app, @EnableScriptsButtonPushed, true);
            app.EnableScriptsButton.Text = {'Enable '; 'PM Scripts'};
            app.StartupScriptButton = uibutton(app.ScriptsTab, 'push');
            app.StartupScriptButton.ButtonPushedFcn = createCallbackFcn(app, @StartupScriptButtonPushed, true);
            app.StartupScriptButton.Text = {'Run Startup'; 'Script'};
            app.PMTab = uitab(app.Commands);
            app.PMTab.Title = 'PM';
            app.PMShutdownButton = uibutton(app.PMTab, 'push');
            app.PMShutdownButton.ButtonPushedFcn = createCallbackFcn(app, @PMShutdownButtonPushed, true);
            app.PMShutdownButton.Text = {'PM'; 'Shutdown'};
            app.PMVersionButton = uibutton(app.PMTab, 'push');
            app.PMVersionButton.ButtonPushedFcn = createCallbackFcn(app, @PMVersionButtonPushed, true);
            app.PMVersionButton.Text = {'PM'; 'Version'};
            app.getPMDateTime = uibutton(app.PMTab, 'push');
            app.getPMDateTime.ButtonPushedFcn = createCallbackFcn(app, @getPMDateTimeButtonPushed, true);
            app.getPMDateTime.Tooltip = {'Set date and time in the power module'};
            app.getPMDateTime.Text = {'Get PM'; 'Date Time'};
            app.setPMDateTime = uibutton(app.PMTab, 'push');
            app.setPMDateTime.ButtonPushedFcn = createCallbackFcn(app, @setPMDateTimeButtonPushed, true);
            app.setPMDateTime.Tooltip = {'Set date and time in the power module'};
            app.setPMDateTime.Text = {'Set PM'; 'Date Time'};
            app.ReadRestoreUserDataButton = uibutton(app.PMTab, 'push');
            app.ReadRestoreUserDataButton.ButtonPushedFcn = createCallbackFcn(app, @ReadRestoreUserDataButtonPushed, true);
            app.ReadRestoreUserDataButton.Text = {'Read'; 'Restore'};
            app.RemoteTab = uitab(app.Commands);
            app.RemoteTab.Title = 'Remote';
            app.RemoteModuleSetupButton = uibutton(app.RemoteTab, 'push');
            app.RemoteModuleSetupButton.ButtonPushedFcn = createCallbackFcn(app, @RemoteModuleSetupButtonPushed, true);
            app.RemoteModuleSetupButton.Tooltip = {'Set up parameters for remote modules'};
            app.RemoteModuleSetupButton.Text = {'Remote '; 'Module'; 'Setup'};
            app.PDOSetupButton = uibutton(app.RemoteTab, 'push');
            app.PDOSetupButton.ButtonPushedFcn = createCallbackFcn(app, @PDOSetupButtonPushed, true);
            app.PDOSetupButton.Tooltip = {'Set up PDOs for PM and remote modules'};
            app.PDOSetupButton.Text = {'PDO'; 'Setup'};
            app.EMGTab = uitab(app.Commands);
            app.EMGTab.Title = 'EMG';
            app.EMGRecordingButton = uibutton(app.EMGTab, 'push');
            app.EMGRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @EMGRecordingButtonPushed, true);
            app.EMGRecordingButton.Text = {'EMG'; 'Recording'};
            app.MessagesTextAreaLabel = uilabel(app.SurgerySuiteUIFigure);
            app.MessagesTextAreaLabel.Text = 'Messages';
            app.MessagesTextArea = uitextarea(app.SurgerySuiteUIFigure);
            app.ODTestButton = uibutton(app.SurgerySuiteUIFigure, 'push');
            app.ODTestButton.ButtonPushedFcn = createCallbackFcn(app, @ODTestButtonPushed, true);
            app.ODTestButton.Text = 'OD Test';
            app.ClearMessageButton = uibutton(app.SurgerySuiteUIFigure, 'push');
            app.ClearMessageButton.Text = 'clear';
            app.ResetUSBButton = uibutton(app.SurgerySuiteUIFigure, 'push');
            app.ResetUSBButton.ButtonPushedFcn = createCallbackFcn(app, @ResetUSBButtonPushed, true);
            app.ResetUSBButton.Tooltip = {'If USB stops responding reset without need to close nnpapi'};
            app.ResetUSBButton.Text = {'Reset'; 'USB'};
            app.PMVersionButton_2 = uibutton(app.SurgerySuiteUIFigure, 'push');
            app.PMVersionButton_2.ButtonPushedFcn = createCallbackFcn(app, @PMVersionButton_2Pushed, true);
            app.PMVersionButton_2.Tooltip = {'Read PM version as test of communications'};
            app.PMVersionButton_2.Text = {'PM'; 'Version'};
            % Show the figure after all components are created
            app.SurgerySuiteUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nnpsurgerysuite

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SurgerySuiteUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SurgerySuiteUIFigure)
        end
    end
end