classdef nodeedit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NodeEditorFigure     matlab.ui.Figure
        UITable              matlab.ui.control.Table
        ApplyButton          matlab.ui.control.Button
        RevertButton         matlab.ui.control.Button
        Panel                matlab.ui.container.Panel
        NodeAvailableLamp    matlab.ui.control.Lamp
        NodePresentbutnotincludedinfileLamp  matlab.ui.control.Lamp
        NodePresentbutnotincludedinfileLamp_2  matlab.ui.control.Lamp
        NodePresentbutnotincludedinfileLamp_3  matlab.ui.control.Lamp
        AvailableLabel       matlab.ui.control.Label
        UnavailableLabel     matlab.ui.control.Label
        UsedLabel            matlab.ui.control.Label
        UnusedLabel          matlab.ui.control.Label
        RefreshStatusButton  matlab.ui.control.Button
        NetworkONButton      matlab.ui.control.Button
        NetworkOFFButton     matlab.ui.control.Button
    end

    
    properties (Access = public)
        parentApp = [] % Description
        nodes = {};
    end
    
    methods (Access = private)
        
        function enableApplyRevert(app, enable)
            app.ApplyButton.Enable = enable;
            app.RevertButton.Enable = enable;
        end
        
        function  populateTable(app)
            maxNodes = 15;
            nodeModes = app.parentApp.nnp.getStatus();
            
            if isempty(nodeModes)
                msgbox('No response from PM' )
                return;
            end
            
            d = cell(maxNodes,4);
            rowColor = zeros(maxNodes, 3);
            for i=1:maxNodes


                d{i,1} = i; %node
                if length(app.nodes)< i || isempty(app.nodes{i})
                    d{i,2} = '-';
                    d{i,3} = '-';
                else
                    d{i,2} = app.nodes{i}.name;
                    d{i,3} = app.nodes{i}.type;
                end
                d{i,4} = nodeModes{i};


                nPatterns = app.parentApp.getPatternCount(i);
                if nPatterns > 0 %Node is used by Patterns
                   if isequal(nodeModes{i}, 'Not Connected')
                       rowColor(i,:) = [1 0 0]; %red: unavailable but attempting to use
                   else
                       rowColor(i,:) = [0 1 0]; %green: available and used
                   end
                   d{i,5} = app.parentApp.getPatternCount(i); 
                else %node unused by Patterns
                   if isequal(nodeModes{i}, 'Not Connected')
                       rowColor(i,:) = [.94 .94 .94]; %gray: unavailable and not used
                   else
                       rowColor(i,:) = [1 1 0]; %yellow: available but unused
                   end
                    d{i,5} = '-';
                end
                    
                if i==7 %PM
                    sn = app.parentApp.nnp.getSerial(7);
                    if length(sn) == 1
                        d{i,6} = ['PM-' num2str(sn) '-App'  ];
                    else
                        d{i,6} = '-';
                    end
                else                   
                    [resp, err] = app.parentApp.nnp.transmit(7, uint8([0, 0, i, 1, 0]), 0, hex2dec('3c'));
                    if err==0 && length(resp) >= 8 
                        if resp(6) > 56  && resp(8)~= i
                            disp('wrong node responding')
                        end
                        snBytes = double(resp(4:5));
                        snStr = num2str(snBytes(2)*256 + snBytes(1));
                        switch resp(3)
                            case {0, 1}
                                mode = 'BL';
                                switch resp(7) 
                                    case 3,    typeStr = 'Test';
                                    case 4,    typeStr = 'PG4';
                                    case 5,    typeStr = 'BP2';
                                    otherwise, typeStr = '?';
                                end
                            case 2
                                mode = 'App';
                                switch resp(2) 
                                    case 4,    typeStr = 'PG4';
                                    case 5,    typeStr = 'BP2';
                                    otherwise, typeStr = '?';
                                 end
                            otherwise
                                mode = '?';
                        end
                        d{i,6} = [typeStr '-' snStr '-' mode ];
                        
                    else
                        d{i,6} = '-';
                    end
                    
                end
               
            end
            app.UITable.BackgroundColor = rowColor;
            app.UITable.Data = d;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, PARENTAPP)
            
            app.parentApp = PARENTAPP;
            app.nodes = app.parentApp.nnps.nodes;

            app.UITable.ColumnFormat = {'char','char','char','char','char'};
            app.populateTable();
            app.enableApplyRevert(false);
          
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            row = event.Indices(1);    
            col = event.Indices(2);    
            
            newData = event.NewData;

            
            switch col
                case 2
                    app.nodes{row}.name = newData;
                case 3
                    app.nodes{row}.min = newData;
                
            end
            app.enableApplyRevert(true);
            
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            if isvalid(app.parentApp)     
                app.parentApp.nnps.nodes = app.nodes;
                app.enableApplyRevert(false);
                app.parentApp.updateDropDowns; 
            else
                warning('parent app closed')
            end  
        end

        % Button pushed function: RevertButton
        function RevertButtonPushed(app, event)
            if isvalid(app.parentApp)     
                app.nodes = app.parentApp.nnps.nodes;
                app.populateTable();
                app.enableApplyRevert(false);
            else
                warning('parent app closed')
            end  
        end

        % Close request function: NodeEditorFigure
        function NodeEditorFigureCloseRequest(app, event)
            if isvalid(app.parentApp)      
                if ~isequal(app.parentApp.nnps.nodes, app.nodes)
                    resp = questdlg('Do you want to apply changes?', 'Node Editor');
                    if isequal(resp, 'Yes')
                        app.ApplyButtonPushed([]);
                    elseif ~isequal(resp, 'No')
                        return
                    end
                    
                end
            else
                warning('parent app closed')
            end
            delete(app)
            
        end

        % Button pushed function: RefreshStatusButton
        function RefreshStatusButtonPushed(app, event)
            app.populateTable();
        end

        % Button pushed function: NetworkONButton
        function NetworkONButtonPushed(app, event)
            app.parentApp.nnp.networkOn();
            pause(1);
            app.populateTable();
        end

        % Button pushed function: NetworkOFFButton
        function NetworkOFFButtonPushed(app, event)
            app.parentApp.nnp.networkOff();
            pause(1);
            app.populateTable();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NodeEditorFigure = uifigure('Visible', 'off');
            app.NodeEditorFigure.Name = 'Node Editor';
            app.NodeEditorFigure.CloseRequestFcn = createCallbackFcn(app, @NodeEditorFigureCloseRequest, true);
            app.UITable = uitable(app.NodeEditorFigure);
            app.UITable.ColumnName = {'Node'; 'Name'; 'Type'; 'Online Mode'; '#Patterns'; 'Serial Number'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true true true true true];
            app.UITable.ColumnEditable = [false true true false false false];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.ApplyButton = uibutton(app.NodeEditorFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.RevertButton = uibutton(app.NodeEditorFigure, 'push');
            app.RevertButton.ButtonPushedFcn = createCallbackFcn(app, @RevertButtonPushed, true);
            app.RevertButton.Text = 'Revert';
            app.Panel = uipanel(app.NodeEditorFigure);
            app.NodeAvailableLamp = uilamp(app.Panel);
            app.NodePresentbutnotincludedinfileLamp = uilamp(app.Panel);
            app.NodePresentbutnotincludedinfileLamp_2 = uilamp(app.Panel);
            app.NodePresentbutnotincludedinfileLamp_3 = uilamp(app.Panel);
            app.AvailableLabel = uilabel(app.Panel);
            app.AvailableLabel.Text = 'Available';
            app.UnavailableLabel = uilabel(app.Panel);
            app.UnavailableLabel.Text = 'Unavailable';
            app.UsedLabel = uilabel(app.Panel);
            app.UsedLabel.Text = 'Used';
            app.UnusedLabel = uilabel(app.Panel);
            app.UnusedLabel.Text = 'Unused';
            app.RefreshStatusButton = uibutton(app.NodeEditorFigure, 'push');
            app.RefreshStatusButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshStatusButtonPushed, true);
            app.RefreshStatusButton.Text = 'Refresh Status';
            app.NetworkONButton = uibutton(app.NodeEditorFigure, 'push');
            app.NetworkONButton.ButtonPushedFcn = createCallbackFcn(app, @NetworkONButtonPushed, true);
            app.NetworkONButton.Text = 'Network ON';
            app.NetworkOFFButton = uibutton(app.NodeEditorFigure, 'push');
            app.NetworkOFFButton.ButtonPushedFcn = createCallbackFcn(app, @NetworkOFFButtonPushed, true);
            app.NetworkOFFButton.Text = 'Network OFF';
            % Show the figure after all components are created
            app.NodeEditorFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nodeedit(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NodeEditorFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NodeEditorFigure)
        end
    end
end