classdef odtest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ObjectDictionaryTestUIFigure  matlab.ui.Figure
        NodeDropDownLabel             matlab.ui.control.Label
        NodeDropDown                  matlab.ui.control.DropDown
        ReadButton                    matlab.ui.control.Button
        WriteButton                   matlab.ui.control.Button
        ODIndexhexEditFieldLabel      matlab.ui.control.Label
        ODIndexEditField              matlab.ui.control.EditField
        SubindexEditFieldLabel        matlab.ui.control.Label
        SubindexEditField             matlab.ui.control.EditField
        Label                         matlab.ui.control.Label
        NEditField                    matlab.ui.control.NumericEditField
        WriteDataEditField            matlab.ui.control.EditField
        ResponseTextAreaLabel         matlab.ui.control.Label
        ResponseTextArea              matlab.ui.control.TextArea
        toPMDropDown                  matlab.ui.control.DropDown
        fromPMDropDown                matlab.ui.control.DropDown
        NMTButton                     matlab.ui.control.Button
        CommandhexEditFieldLabel      matlab.ui.control.Label
        CommandEditField              matlab.ui.control.EditField
        Param1hexEditFieldLabel       matlab.ui.control.Label
        Param1EditField               matlab.ui.control.EditField
        Param2hexEditFieldLabel       matlab.ui.control.Label
        Param2EditField               matlab.ui.control.EditField
        ClearButton                   matlab.ui.control.Button
    end


    properties (Access = public)
        nnp = []; 
    end

    properties (Access = private)

    end

    methods (Access = public)


    end

    methods (Access = private)


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, NNP)
            if isempty(NNP)
                app.nnp = NNPAPI();
                if isempty(app.nnp.port)
                    msgbox('No port chosen for NNP Access Point')
                    delete(app.nnp)
                    return
                end
            else
                app.nnp = NNP;
            end
            
        end

        % Button pushed function: WriteButton
        function WriteButtonPushed(app, event)
            node = str2double(app.NodeDropDown.Value);
            odIndex = app.ODIndexEditField.Value;
            subindex = app.SubindexEditField.Value;
            n = app.NEditField.Value;
            
            if isempty(odIndex) || isempty(subindex)
                msgbox('OD index and subindex fields cannot be empty')
                return;
            end
                        
            type = app.toPMDropDown.Value;
            if isequal(type, 'hex byte')
                data = sscanf(app.WriteDataEditField.Value, '%02X ')';
                 resp = app.nnp.write(node, odIndex, subindex, data, n);
            else    
                data = str2num(app.WriteDataEditField.Value);
                resp = app.nnp.write(node, odIndex, subindex, data, n, type);
            end
           
            if ~isempty(resp)
                app.ResponseTextArea.Value = [sprintf('<Write Response: %02X>', resp) ; app.ResponseTextArea.Value];
            else
                app.ResponseTextArea.Value = ['<Error:' app.nnp.lastError '>' ; app.ResponseTextArea.Value];
            end
        end

        % Button pushed function: ReadButton
        function ReadButtonPushed(app, event)
            node = str2double(app.NodeDropDown.Value);
            odIndex = app.ODIndexEditField.Value;
            subindex = app.SubindexEditField.Value;
            n = app.NEditField.Value;
            type = app.fromPMDropDown.Value;
            
            if isempty(odIndex) || isempty(subindex)
                msgbox('OD index and subindex fields cannot be empty')
                return;
            end
                        
            if isequal(type, 'hex byte')
                resp = app.nnp.read(node, odIndex, subindex, n);
            else
                resp = app.nnp.read(node, odIndex, subindex, n, type);
            end
            

            if ~isempty(resp)
                if isequal(type, 'hex byte')
                    str = sprintf('%02X ', resp);
                else
                    str = num2str(resp);
                end
                app.ResponseTextArea.Value = [str; app.ResponseTextArea.Value];
            else
                app.ResponseTextArea.Value = ['<Error:' app.nnp.lastError '>'; app.ResponseTextArea.Value];
            end
        end

        % Button pushed function: NMTButton
        function NMTButtonPushed(app, event)
            node = str2double(app.NodeDropDown.Value);
            cmd = app.CommandEditField.Value;
            p1 = sscanf(app.Param1EditField.Value, '%02X');
            p2 = sscanf(app.Param2EditField.Value, '%02X');
            if isempty(cmd)
                msgbox('NMT command field cannot be empty')
                return;
            end
            if isempty(p2) || isempty(p2)
                if isempty(p1)
                    resp = app.nnp.nmt(node, cmd);
                else
                    resp = app.nnp.nmt(node, cmd, p1);
                end
            else
                resp = app.nnp.nmt(node, cmd, p1, p2);
            end
            if ~isempty(resp)
                app.ResponseTextArea.Value = [sprintf('<NMT Response: %02X>', resp); app.ResponseTextArea.Value];
            else
                app.ResponseTextArea.Value = ['<Error:' app.nnp.lastError '>' ; app.ResponseTextArea.Value];
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.ResponseTextArea.Value = '';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ObjectDictionaryTestUIFigure = uifigure('Visible', 'off');
            app.ObjectDictionaryTestUIFigure.Name = 'Object Dictionary Test';
            app.NodeDropDownLabel = uilabel(app.ObjectDictionaryTestUIFigure);
            app.NodeDropDownLabel.Text = 'Node';
            app.NodeDropDown = uidropdown(app.ObjectDictionaryTestUIFigure);
            app.NodeDropDown.Items = {'0 (broadcast)', '1', '2', '3', '4', '5', '6', '7 (PM)', '9', '10'};
            app.NodeDropDown.ItemsData = {'0', '1', '2', '3', '4', '5', '6', '7', '9', '10'};
            app.NodeDropDown.Value = '7';
            app.ReadButton = uibutton(app.ObjectDictionaryTestUIFigure, 'push');
            app.ReadButton.ButtonPushedFcn = createCallbackFcn(app, @ReadButtonPushed, true);
            app.ReadButton.Text = 'Read';
            app.WriteButton = uibutton(app.ObjectDictionaryTestUIFigure, 'push');
            app.WriteButton.ButtonPushedFcn = createCallbackFcn(app, @WriteButtonPushed, true);
            app.WriteButton.Text = 'Write';
            app.ODIndexhexEditFieldLabel = uilabel(app.ObjectDictionaryTestUIFigure);
            app.ODIndexhexEditFieldLabel.Text = 'OD Index (hex)';
            app.ODIndexEditField = uieditfield(app.ObjectDictionaryTestUIFigure, 'text');
            app.SubindexEditFieldLabel = uilabel(app.ObjectDictionaryTestUIFigure);
            app.SubindexEditFieldLabel.Text = 'Subindex (hex)';
            app.SubindexEditField = uieditfield(app.ObjectDictionaryTestUIFigure, 'text');
            app.Label = uilabel(app.ObjectDictionaryTestUIFigure);
            app.Label.Text = '#';
            app.NEditField = uieditfield(app.ObjectDictionaryTestUIFigure, 'numeric');
            app.NEditField.Limits = [1 255];
            app.NEditField.Value = 1;
            app.WriteDataEditField = uieditfield(app.ObjectDictionaryTestUIFigure, 'text');
            app.ResponseTextAreaLabel = uilabel(app.ObjectDictionaryTestUIFigure);
            app.ResponseTextAreaLabel.Text = 'Response';
            app.ResponseTextArea = uitextarea(app.ObjectDictionaryTestUIFigure);
            app.toPMDropDown = uidropdown(app.ObjectDictionaryTestUIFigure);
            app.toPMDropDown.Items = {'hex byte', 'uint8', 'int8', 'uint16', 'int16', 'uint32', 'int32', 'string'};
            app.toPMDropDown.Value = 'hex byte';
            app.fromPMDropDown = uidropdown(app.ObjectDictionaryTestUIFigure);
            app.fromPMDropDown.Items = {'hex byte', 'uint8', 'int8', 'uint16', 'int16', 'uint32', 'int32', 'string'};
            app.fromPMDropDown.Value = 'hex byte';
            app.NMTButton = uibutton(app.ObjectDictionaryTestUIFigure, 'push');
            app.NMTButton.ButtonPushedFcn = createCallbackFcn(app, @NMTButtonPushed, true);
            app.NMTButton.Text = 'NMT';
            app.CommandhexEditFieldLabel = uilabel(app.ObjectDictionaryTestUIFigure);
            app.CommandhexEditFieldLabel.Text = 'Command (hex)';
            app.CommandEditField = uieditfield(app.ObjectDictionaryTestUIFigure, 'text');
            app.Param1hexEditFieldLabel = uilabel(app.ObjectDictionaryTestUIFigure);
            app.Param1hexEditFieldLabel.Text = 'Param1 (hex)';
            app.Param1EditField = uieditfield(app.ObjectDictionaryTestUIFigure, 'text');
            app.Param2hexEditFieldLabel = uilabel(app.ObjectDictionaryTestUIFigure);
            app.Param2hexEditFieldLabel.Text = 'Param2 (hex)';
            app.Param2EditField = uieditfield(app.ObjectDictionaryTestUIFigure, 'text');
            app.ClearButton = uibutton(app.ObjectDictionaryTestUIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            % Show the figure after all components are created
            app.ObjectDictionaryTestUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = odtest(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ObjectDictionaryTestUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ObjectDictionaryTestUIFigure)
        end
    end
end