classdef patternAutoCreate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CreatePatternGroupUIFigure  matlab.ui.Figure
        CreatePatternGroupFromTemplateLabel  matlab.ui.control.Label
        ProfileBeingUsedLabel       matlab.ui.control.Label
        NameEditField               matlab.ui.control.EditField
        NameEditFieldLabel          matlab.ui.control.Label
        ProfileUITable              matlab.ui.control.Table
        MessagesTextArea            matlab.ui.control.TextArea
        MessagesTextAreaLabel       matlab.ui.control.Label
        ClearMessageButton          matlab.ui.control.Button
        CancelButton                matlab.ui.control.Button
        KeepButton                  matlab.ui.control.Button
        CreatePatternGroupButton    matlab.ui.control.Button
        TemplateDropDown            matlab.ui.control.DropDown
        TemplateDropDownLabel       matlab.ui.control.Label
        cmdIDEditField              matlab.ui.control.NumericEditField
        cmdIDEditFieldLabel         matlab.ui.control.Label
        GroupEditField              matlab.ui.control.NumericEditField
        GroupEditFieldLabel         matlab.ui.control.Label
        PWAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        newPatterns=[]; 
        templateStructure=[]; %fields: 'templateSize','templateCommandArray','templateNames','templateMuscleTypes','templateMatrix'
        profileTable=[];
        colNum=struct('LastChecked', 1, 'Node', 2, 'Ch', 3, 'Muscle', 4,...
                      'Amp', 5, 'ThPW', 6, 'MaxPW', 7, 'Type', 8, 'Flag', 9, 'Notes', 10);
        saveFile=[];
        Plots=[];
        pwLegend=[];
        maxType=13;
        template=[]; % we could just use the template structure, but I chose such long names, this was easier...
        templateName=[];
        commandArray;
        commandLevels;
        createdPatterns=[];
        usedProfRows=[];
        parentApp = [];
        keepOrCancel=false;
        callingFigure=[];
        ltYellowBG=[1,1,0.8];
        whiteBG=[1,1,1];
        ltBlueBG=[.8,1,1];

    end
    
    methods (Access = public)
        
        function typeIndex = getTypeIndex(app,mtype)
          tIndex=find(strcmp(mtype,app.templateStructure.templateMuscleTypes.MuscleType));
          if ~isempty(tIndex)
             typeIndex=tIndex; 
          else
             typeIndex=app.maxType; % other 
          end
        end

        function createPatterns(app)
        % for each row in the profile, calculate the associated pattern
        % structure, which consists of group, node, ch, cmdID, n, cmd, pw, amp
        % colNum=struct('LastChecked', 1, 'Node', 2, 'Ch', 3, 'Muscle', 4,...
        % 'Amp', 5, 'ThPW', 6, 'MaxPW', 7, 'Type', 8, 'Flag', 9, 'Notes', 10);
            profileRows=size(app.profileTable,1); % we could do this without the table eventually
            app.newPatterns={}; % clear the array before starting.  Only needed if running a second time around
            patRow=0; % only create patterns for rows used in pattern group
            bgColor=ones(profileRows,3); %start all white
            for profRow=1:profileRows
                mtype=app.profileTable{profRow,app.colNum.Type};
                tInd = app.getTypeIndex(mtype); % index into the template array, app.template
                if tInd>0&&tInd<app.maxType % if OTH or undefined, do not create pattern
                    % check whether used at all in this template
                    if sum(app.template(tInd,:))>0 % check whether used at all in this template
                        patRow=patRow+1; % new pattern row
                        app.newPatterns{patRow}.group=app.GroupEditField.Value; % could make this a property
                        app.newPatterns{patRow}.node=app.profileTable{profRow,app.colNum.Node};
                        app.newPatterns{patRow}.ch=app.profileTable{profRow,app.colNum.Ch};
                        app.newPatterns{patRow}.cmdID=app.cmdIDEditField.Value;
                        app.newPatterns{patRow}.n=app.commandLevels;
                        app.newPatterns{patRow}.cmd=app.commandArray;
                        templateRow=app.template(tInd,:); % use for calculations
                        pw=zeros(1,9);% start with all zeros
                        thPW=app.profileTable{profRow,app.colNum.ThPW};
                        maxPW=app.profileTable{profRow,app.colNum.MaxPW};
                        ramp=maxPW-thPW;
                        % 1=thresh, 2=max, 1.x is somewhere in between
                        for i=1:app.commandLevels
                            if templateRow(i)<1 % below threshold
                                pw(i)=0;
                            elseif templateRow(i)>=2 % at max
                                pw(i)=maxPW;
                            else
                                pw(i)=round(thPW+ramp*(templateRow(i)-1),0); %interpolate and round to nearest integer
                            end
                        end

                    app.newPatterns{patRow}.pw=pw;
                    app.newPatterns{patRow}.amp=ones(1,app.commandLevels)*app.profileTable{profRow,app.colNum.Amp};

                    % Plot on the Graph
                     if patRow == 1
                        hold(app.PWAxes, 'off');
                        % use sprintf to get better formatting of title 
                        G=app.GroupEditField.Value;
                        C=app.cmdIDEditField.Value;
                        N=app.NameEditField.Value;
                        T=app.templateName;
                        plotTitle=sprintf('FG %d, Cmd %d,  T-%s,  %s',G, C, T, N);
                        app.PWAxes.Title.String=plotTitle;
                    else
                        hold(app.PWAxes, 'on');
                     end
                     % Note, if I wanted to be able to highlight the plots
                     % on the graph based on the template row, I would need
                     % to create a map between patRow and profRow
                     app.usedProfRows(patRow)=profRow;
                     % use this later to highlight plots

          % Highlight Profile Rows
                     % have been used like we do with Action Maps, actionPatternMap.mlapp
                    % See function ActionMappingTableCellSelection(app,
                    % event) for how to do this
                    bgColor(profRow,:)=app.ltYellowBG; %make used row yellow

                    app.Plots{patRow}=plot(app.PWAxes,app.commandArray,pw,'LineStyle','-','Marker','.','LineWidth',1,'MarkerSize',10);
                    legendList{patRow}=char(app.ProfileUITable.Data{profRow,app.colNum.Muscle}); % add type here
                    
                    end
                end
                app.ProfileUITable.BackgroundColor=bgColor; % set background color of used rows
            end
        if size(app.newPatterns,1)>0
            app.newPatterns=app.newPatterns'; % the array is indexed by column by default
            app.pwLegend=legend(app.PWAxes,legendList,'Location','eastoutside');
            app.KeepButton.Enable=true;
        else
            app.KeepButton.Enable=false;
        end
        end
        
        function TopMessage(app,message)
             app.MessagesTextArea.Value=vertcat(message, string(app.MessagesTextArea.Value)); % put new message on top
        end
        
        function ClearMessages(app)
            app.MessagesTextArea.Value=''; % clear messages
        end
    end
    
    methods (Access = private)
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
          %      PARENTAPP, templateStructure, profileTable, group, cmdID,
          %      name

            app.parentApp = varargin{1};
                
            app.templateStructure=varargin{2};
            app.profileTable=cell2table(varargin{3}); % cell array 12x10
            app.NameEditField.Value=varargin{6};
            app.TemplateDropDown.Items=app.templateStructure.templateNames;            
            app.TemplateDropDown.ItemsData=[1:app.templateStructure.templateSize];
            app.templateName=app.TemplateDropDown.Items{1}; %set as first by default          
            app.maxType=size(app.templateStructure.templateMuscleTypes.MuscleType,1);
            app.GroupEditField.Value=varargin{4};
            app.cmdIDEditField.Value=varargin{5};
            app.commandArray=app.templateStructure.templateCommandArray;
            app.commandLevels=size(app.commandArray,2);
            app.template(:,:)=app.templateStructure.templateMatrix(1,:,:); % default is first in dropdown
            app.ProfileUITable.Data = app.profileTable;
            if ishandle(app.parentApp.PatternGroupEditorFigure)
                    uiwait(app.parentApp.PatternGroupEditorFigure);
            end
        end

        % Button pushed function: CreatePatternGroupButton
        function CreatePatternGroupButtonPushed(app, event)
            app.createPatterns();
        end

        % Value changed function: TemplateDropDown
        function TemplateDropDownValueChanged(app, event)
            value = app.TemplateDropDown.Value;
            app.templateName=app.TemplateDropDown.Items{value};
            app.template(:,:)=app.templateStructure.templateMatrix(value,:,:); 
        end

        % Button pushed function: KeepButton
        function KeepButtonPushed(app, event)
            app.parentApp.createdPatterns=app.newPatterns;
            app.parentApp.addCreatedPatterns=true;
            app.keepOrCancel=true;
             app.CreatePatternGroupUIFigureCloseRequest();
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.parentApp.createdPatterns=[];
            app.parentApp.addCreatedPatterns=false;
            app.keepOrCancel=true;
             app.CreatePatternGroupUIFigureCloseRequest();
        end

        % Close request function: CreatePatternGroupUIFigure
        function CreatePatternGroupUIFigureCloseRequest(app, event)
            if ~app.keepOrCancel
                %if not selected, then treat like cancel
                app.parentApp.createdPatterns=[];
                app.parentApp.addCreatedPatterns=false;
            end
            if ishandle(app.parentApp.PatternGroupEditorFigure)
                    uiresume(app.parentApp.PatternGroupEditorFigure);
                    figure(app.parentApp.PatternGroupEditorFigure);
            end
          
            delete(app)
            
        end

        % Callback function: ProfileUITable
        function ProfileUITableSelectionChanged(app, event)
            selection = app.ProfileUITable.Selection;
            % selection type is row selection
            if ~isempty(app.usedProfRows)
                patLength=size(app.Plots,2);
                for i=1:patLength
                    if selection==app.usedProfRows(i)
                        width=2;
                    else
                        width=1;
                    end
                    app.Plots{i}.LineWidth=width;
                end
% Here I would plan to step through all the plots, app.Plots() and change
% the line thicknesses based on whether 
            end
        end

        % Value changed function: cmdIDEditField
        function cmdIDEditFieldValueChanged(app, event)
            value = app.cmdIDEditField.Value;
            app.parentApp.newcmdID=value;
        end

        % Value changed function: NameEditField
        function NameEditFieldValueChanged(app, event)
            value = app.NameEditField.Value;
            app.parentApp.newName=value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CreatePatternGroupUIFigure = uifigure('Visible', 'off');
            app.CreatePatternGroupUIFigure.Name = 'Create Pattern Group';
            app.CreatePatternGroupUIFigure.CloseRequestFcn = createCallbackFcn(app, @CreatePatternGroupUIFigureCloseRequest, true);
            app.PWAxes = uiaxes(app.CreatePatternGroupUIFigure);
            title(app.PWAxes, 'Title')
            xlabel(app.PWAxes, 'Command')
            ylabel(app.PWAxes, 'PW')
            zlabel(app.PWAxes, 'Z')
            app.PWAxes.XLim = [0 255];
            app.PWAxes.YLim = [0 255];
            app.PWAxes.XTick = [0 32 64 96 128 160 192 224 255];
            app.PWAxes.XTickLabel = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'};
            app.PWAxes.YTick = [0 50 100 150 200 255];
            app.PWAxes.YTickLabel = {'0'; '50'; '100'; '150'; '200'; '255'};
            app.GroupEditFieldLabel = uilabel(app.CreatePatternGroupUIFigure);
            app.GroupEditFieldLabel.Text = 'Group';
            app.GroupEditField = uieditfield(app.CreatePatternGroupUIFigure, 'numeric');
            app.GroupEditField.Editable = 'off';
            app.cmdIDEditFieldLabel = uilabel(app.CreatePatternGroupUIFigure);
            app.cmdIDEditFieldLabel.Text = 'cmdID';
            app.cmdIDEditField = uieditfield(app.CreatePatternGroupUIFigure, 'numeric');
            app.cmdIDEditField.Limits = [1 8];
            app.cmdIDEditField.ValueChangedFcn = createCallbackFcn(app, @cmdIDEditFieldValueChanged, true);
            app.cmdIDEditField.Value = 1;
            app.TemplateDropDownLabel = uilabel(app.CreatePatternGroupUIFigure);
            app.TemplateDropDownLabel.Text = 'Template';
            app.TemplateDropDown = uidropdown(app.CreatePatternGroupUIFigure);
            app.TemplateDropDown.ValueChangedFcn = createCallbackFcn(app, @TemplateDropDownValueChanged, true);
            app.CreatePatternGroupButton = uibutton(app.CreatePatternGroupUIFigure, 'push');
            app.CreatePatternGroupButton.ButtonPushedFcn = createCallbackFcn(app, @CreatePatternGroupButtonPushed, true);
            app.CreatePatternGroupButton.Text = 'Create Pattern Group';
            app.KeepButton = uibutton(app.CreatePatternGroupUIFigure, 'push');
            app.KeepButton.ButtonPushedFcn = createCallbackFcn(app, @KeepButtonPushed, true);
            app.KeepButton.Enable = 'off';
            app.KeepButton.Text = 'Keep';
            app.CancelButton = uibutton(app.CreatePatternGroupUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ClearMessageButton = uibutton(app.CreatePatternGroupUIFigure, 'push');
            app.ClearMessageButton.Text = 'clear';
            app.MessagesTextAreaLabel = uilabel(app.CreatePatternGroupUIFigure);
            app.MessagesTextAreaLabel.Text = 'Messages';
            app.MessagesTextArea = uitextarea(app.CreatePatternGroupUIFigure);
            app.ProfileUITable = uitable(app.CreatePatternGroupUIFigure);
            app.ProfileUITable.ColumnName = {'Last Checked'; 'Node'; 'Ch'; 'Muscle'; 'Amp'; 'ThPW'; 'MaxPW'; 'Type'; '!'; 'Notes'};
            app.ProfileUITable.RowName = {};
            app.ProfileUITable.ColumnSortable = [true true true false false false false true false false];
            app.ProfileUITable.SelectionType = 'row';
            app.ProfileUITable.SelectionChangedFcn = createCallbackFcn(app, @ProfileUITableSelectionChanged, true);
            app.ProfileUITable.Multiselect = 'off';
            app.NameEditFieldLabel = uilabel(app.CreatePatternGroupUIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.CreatePatternGroupUIFigure, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @NameEditFieldValueChanged, true);
            app.NameEditField.Value = 'name';
            app.ProfileBeingUsedLabel = uilabel(app.CreatePatternGroupUIFigure);
            app.ProfileBeingUsedLabel.Text = 'Profile Being Used:';
            app.CreatePatternGroupFromTemplateLabel = uilabel(app.CreatePatternGroupUIFigure);
            app.CreatePatternGroupFromTemplateLabel.Text = 'Create Pattern Group From Template';
            % Show the figure after all components are created
            app.CreatePatternGroupUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = patternAutoCreate(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CreatePatternGroupUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CreatePatternGroupUIFigure)
        end
    end
end