classdef patternEditKeyPad < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        NewMenu                     matlab.ui.container.Menu
        OpenMenu                    matlab.ui.container.Menu
        SaveMenu                    matlab.ui.container.Menu
        SaveAsMenu                  matlab.ui.container.Menu
        SystemMenu                  matlab.ui.container.Menu
        GroupsMenu                  matlab.ui.container.Menu
        NodesMenu                   matlab.ui.container.Menu
        ChannelsMenu                matlab.ui.container.Menu
        LoadfromMATfileMenu         matlab.ui.container.Menu
        LoadCNGPfromNNPSFileMenu    matlab.ui.container.Menu
        NetworkMenu                 matlab.ui.container.Menu
        NetworkOffMenu              matlab.ui.container.Menu
        NeworkOnMenu                matlab.ui.container.Menu
        ScriptsMenu                 matlab.ui.container.Menu
        ScriptsDisableMenu          matlab.ui.container.Menu
        ScriptsEnableMenu           matlab.ui.container.Menu
        WORMenu                     matlab.ui.container.Menu
        WOROffMenu                  matlab.ui.container.Menu
        WOROnMenu                   matlab.ui.container.Menu
        HelpMenu                    matlab.ui.container.Menu
        HelpSubMenu                 matlab.ui.container.Menu
        AboutMenu                   matlab.ui.container.Menu
        CommunicationsIssuesMenu    matlab.ui.container.Menu
        NumpadHelpMenu              matlab.ui.container.Menu
        ProfileMenu                 matlab.ui.container.Menu
        LoadProfile                 matlab.ui.container.Menu
        ExportMenu                  matlab.ui.container.Menu
        SaveActiveFGMenu            matlab.ui.container.Menu
        ExportActiveFGMenu          matlab.ui.container.Menu
        ExportAllFGtoPNGXLSMenu     matlab.ui.container.Menu
        UserIDEditField             matlab.ui.control.EditField
        StimModeSwitch              matlab.ui.control.Switch
        StimModeSwitchLabel         matlab.ui.control.Label
        GraphTabGroup               matlab.ui.container.TabGroup
        PWTab                       matlab.ui.container.Tab
        NumPadHelpPanel             matlab.ui.container.Panel
        Image                       matlab.ui.control.Image
        PWAxes                      matlab.ui.control.UIAxes
        AmpTab                      matlab.ui.container.Tab
        PAAxes                      matlab.ui.control.UIAxes
        OverrideNumlockCheckBox     matlab.ui.control.CheckBox
        Lamp                        matlab.ui.control.Lamp
        clearButton                 matlab.ui.control.Button
        KeyboardControlsPanel       matlab.ui.container.Panel
        ShowButton                  matlab.ui.control.Button
        FastholdCTLInsertLabel      matlab.ui.control.Label
        PgDnButton_2                matlab.ui.control.Button
        PgUpButton                  matlab.ui.control.Button
        x10Label_2                  matlab.ui.control.Label
        ALTButton_2                 matlab.ui.control.Button
        ChannelLabel                matlab.ui.control.Label
        PgDnButton                  matlab.ui.control.Button
        ALTButton                   matlab.ui.control.Button
        PWPAValueLabel              matlab.ui.control.Label
        CommandValueLabel           matlab.ui.control.Label
        BreakpointsLabel_2          matlab.ui.control.Label
        Button_6                    matlab.ui.control.Button
        Button_5                    matlab.ui.control.Button
        SPACECTRLButton             matlab.ui.control.Button
        StimONOFFLabel              matlab.ui.control.Label
        SPACEButton                 matlab.ui.control.Button
        Button_4                    matlab.ui.control.Button
        Button_3                    matlab.ui.control.Button
        Button_2                    matlab.ui.control.Button
        Button                      matlab.ui.control.Button
        DownloadableCheckBox        matlab.ui.control.CheckBox
        ErrorsTextArea              matlab.ui.control.TextArea
        ErrorsTextAreaLabel         matlab.ui.control.Label
        RemoveChannelPatternButton  matlab.ui.control.Button
        AddChannelPatternButton     matlab.ui.control.Button
        PatternValuesPanel          matlab.ui.container.Panel
        SetAllAmpButton             matlab.ui.control.Button
        SetAllPWButton              matlab.ui.control.Button
        PatternValuesTable          matlab.ui.control.Table
        ButtonBottom                matlab.ui.control.Button
        ButtonTop                   matlab.ui.control.Button
        ButtonDown                  matlab.ui.control.Button
        ButtonUp                    matlab.ui.control.Button
        Panel                       matlab.ui.container.Panel
        AllMuteCheckBox             matlab.ui.control.CheckBox
        PatternTableHeadings        matlab.ui.control.Table
        ColorTable                  matlab.ui.control.Table
        PatternTable                matlab.ui.control.Table
        SystemPatternsPanel         matlab.ui.container.Panel
        LoadReadPatternsintoEditorButton  matlab.ui.control.Button
        EraseButton                 matlab.ui.control.Button
        CurrentOnlyCheckBox         matlab.ui.control.CheckBox
        CompareButton               matlab.ui.control.Button
        ReadButton                  matlab.ui.control.Button
        WriteButton                 matlab.ui.control.Button
        NodeDropDown                matlab.ui.control.DropDown
        NodeDropDownLabel           matlab.ui.control.Label
        PatternGroupDropDown        matlab.ui.control.DropDown
        PatternGroupDropDownLabel   matlab.ui.control.Label
        StimulationPanel            matlab.ui.container.Panel
        Scpt11Button                matlab.ui.control.Button
        PWLabel                     matlab.ui.control.Label
        PWEditField                 matlab.ui.control.NumericEditField
        MuscleEditField             matlab.ui.control.EditField
        SubindexEditField           matlab.ui.control.NumericEditField
        SubindexEditFieldLabel      matlab.ui.control.Label
        CommandfromPMCheckBox       matlab.ui.control.CheckBox
        CommandEditField            matlab.ui.control.NumericEditField
        CommandLabel                matlab.ui.control.Label
        HighBandwidthCheckBox       matlab.ui.control.CheckBox
        StimOFFButton               matlab.ui.control.Button
        StimONButton                matlab.ui.control.Button
        EditModeSwitch              matlab.ui.control.Switch
        EditModeSwitchLabel         matlab.ui.control.Label
    end


    properties (Access = public)
        editMode = 'x'; % Description
        viewMode = 'patterngroup'
        MinMaxPatch = [];
        Plots = [];
        SmallPlots = [];
        PlotBars = [];
        PlotSlider = [];
        PlotSliderSmall = [];
        colorOrder = [ 1.0000   0.0000    0.0000 %red
            1.0000   0.6471    0.0000 %orange
            1.0000   0.8431    0.0000 %yellow
            0.1961   0.8039    0.1961 %green
            0.0000   1.0000    1.0000 %cyan
            0.0000   0.0000    1.0000 %blue
            1.0000   0.0000    1.0000 %magenta
            0.6471   0.1647    0.1647]; %brown
        markerOrder = 'osv^><';
        markerOrderLabel = '●■▼▲►◄'
        Patterns = {};
        ReadPatterns = {};
        ActivePatterns = {};
        UnselectedColor;
        activePatternRow;
        nnps =[]; %NNP settings file for user (group, channel names)
        nnp = []; %handle to NNPAPI
        Y = [];
        saveFile=[];
        groupEditUI=[];
        channelEditUI=[];
        nodeEditUI=[];
        stimOn = false;
        reload;
        callingFigure=[]; %handle to calling figure
        pmCommandTimer % Timer used to get PM:OD-based updates
        % for fast ramp up on hold
        checkLockout=false; % look for lockout - reset on KeyRelease
        lastUpdate=0; % last time the variable was updated
        
        repeatOn=false; % key repeated before release
        bigStep=10;
        littleStep=1;
        stepSizeMult=3;
        stepSize=1; %step size whien updating
        keyRelUpdate=false; % update on keyRelease if a second keyPresss doesn't occur
        lockoutSec=0.250; % delay to wait between updates when held
        newTime=0; %time since last update
        lockoutCounter=0; % when multiple keypresses are detected without key release, this increments
        lockoutCountLimit=10; % may want to set this to be the same as bigSteps, but for now make separate
        lastStimUpdate=0;
        stimUpdateLimit=0.25;  %250ms between updates
        profileName=[]; % including path - which profile current in use.
        % columns {'Last Checked'; 'Node'; 'Ch'; 'Muscle'; 'Amp';'ThPW'; 'MaxPW'; 'Type'; '!'; 'Notes'};
        % define the columns in case the profile format changes:
%         colNum=struct('LastChecked', 1, 'Node', 2, 'Ch', 3, 'Muscle', 4,...
%        'Amp', 5, 'ThPW', 6, 'MaxPW', 7, 'Type', 8, 'Flag', 9, 'Notes', 10);
        showNumpadImage=false;
% Code for auto file open
         userFolders=struct;  % userfiles, code, user, config, data, log
         defaultPath=pwd;
         defaultSuffix='_Patterns.mat';
         defaultName='P00_Patterns.mat';
         defaultProfileSuffix='_Profile.mat';
         defaultProfileName='P00_Profile.mat';
    end

    properties (Access = private)
        altPressed = false;
        shiftPressed = false;
        ctrlPressed = false;
        clearPressed = false;
        insertPressed = false;
        updatingStim = false;
    end


    methods (Access = public)

        function SliderClickCallback(app, src, evt )
            %disp('Clicked Slider')
            if app.CommandfromPMCheckBox.Value
                msgbox('Cannot move slider while "Command from PM" is enabled')
                return
            end
            hLine = src;
            ax = src.Parent;
            app.UIFigure.WindowButtonMotionFcn = {@app.MovePointCallback, ax, hLine, 'Slider'};
            app.UIFigure.WindowButtonUpFcn = {@app.ReleaseClickCallback,ax, hLine, 'Slider'};
        end

        function LineClickCallback(app, src, evt )
            %LINECLICK Called on a click of a pattern or breakpoint line
            %
            % If 'HitTest' is set to 'on' clicking the line will trigger this callback
            % (not sure if threshold for click can be adjusted.  It doesn't seem
            % affected by line thickness)
            %  HitTest      pattern lines    breakpoint lines
            %  y-edit mode  ON               OFF
            %  x-edit mode  OFF              ON
            %
            % src is the handle of the clicked line
            %       (pattern in y-edit mode OR breakpoint in x-edit mode)
            % evt is used to find the clicked point
            %
            %  The following inputs are only required for x-edit mode since all lines
            %  need to be updated when changing x-value, but are also passed during
            %  y-edit mode for simplicity:
            % hPlots are handles to all the Patterns plotted
            % hPlotBars are handles to all the Breakpoint lines
            %
            % Note:
            % axis CurrentPoint reports non NaN values when outside of plot area
            % figure CurrentPoint reports values in figure pixels, not axis units
            % evt.IntersectionPoint reports NaN values when outside plot area


            breakPointThreshold = 4; %if this is too low, we may create a new
            maxBreakPoints = 20;
            %breakpoint at the same time that we remove one

            xc = evt.IntersectionPoint(1);
            yc = evt.IntersectionPoint(2);
            hLine = src;
            ax = src.Parent;

            % Callback entered on line click. Find closest point on the line
            switch app.editMode
                case 'y'
                    y=abs(hLine.YData-yc);
                    x=abs(hLine.XData-xc);
                    d=y.^2+x.^2;
                    index=find(d==min(d));


                    %update PatternValues Table and PatternTable Selction
                    if ax == app.PWAxes
                        row = find(cellfun(@(x) x==hLine, app.Plots));
                    elseif ax == app.PAAxes
                        row = find(cellfun(@(x) x==hLine, app.SmallPlots));
                    end
                    if isempty(row)
                        warning('Could not determine active pattern')
                    else
                        %disp(['click on pattern row:' num2str(row)])
                        app.updateActivePattern(row);
                    end


                case 'x'
                    switch evt.Button
                        case 1 %left click
                            index = find(cellfun(@(x) x==hLine, app.PlotBars))+1;
                        case 3 %right click

                            index = find(cellfun(@(x) x==hLine, app.PlotBars))+1; %find breakpoint that matches current line handle
                            if isempty(index) %line handle must be a pattern line
                                xc = round(xc);
                                if length(app.Plots{1}.XData)>=20
                                    msgbox('Max breakpoints already present')
                                    return;
                                end
                                if any(abs(app.Plots{1}.XData-xc)<breakPointThreshold)
                                    msgbox('Breakpoint already exists at this value')
                                    return;
                                end
                                %insert the new breakpoint
                                before = app.Plots{1}.XData<xc;
                                after  = app.Plots{1}.XData>xc;
                                xbefore = app.Plots{1}.XData(before);
                                xafter = app.Plots{1}.XData(after);
                                for i=1:length(app.Plots)
                                    ybefore = app.Plots{i}.YData(before);
                                    yafter = app.Plots{i}.YData(after);
                                    %interpolate new y value
                                    yc = round(ybefore(end) + (xc-xbefore(end))*(yafter(1)-ybefore(end))/(xafter(1)-xbefore(end)));
                                    app.Plots{i}.XData = [xbefore, xc, xafter];
                                    app.Plots{i}.YData = [ybefore, yc, yafter];
                                end
                                for i=1:length(app.SmallPlots)
                                    ybefore = app.SmallPlots{i}.YData(before);
                                    yafter = app.SmallPlots{i}.YData(after);
                                    %interpolate new y value
                                    yc = round(ybefore(end) + (xc-xbefore(end))*(yafter(1)-ybefore(end))/(xafter(1)-xbefore(end)));
                                    app.SmallPlots{i}.XData = [xbefore, xc, xafter];
                                    app.SmallPlots{i}.YData = [ybefore, yc, yafter];
                                end
                                index = length(xbefore);
                                for i=length(app.PlotBars):-1:index+1
                                    app.PlotBars{i}.XData = app.PlotBars{i-1}.XData;
                                end
                                app.PlotBars{index}.XData = [xc xc];


                                %data = [app.PatternValuesTable.Data(1:index,:); [xc, y_pw, y_amp]; app.PatternValuesTable.Data(index+1:end,:)];
                                x  = app.Plots{app.activePatternRow}.XData';
                                pw = app.Plots{app.activePatternRow}.YData';
                                amp = app.SmallPlots{app.activePatternRow}.YData';
                                app.PatternValuesTable.Data = [x, pw, amp];

                            else
                                resp = questdlg('Delete Breakpoint?');
                                if strcmp(resp, 'Yes')
                                    for i=1:length(app.Plots)
                                        app.Plots{i}.XData = [app.Plots{i}.XData(1:index-1) app.Plots{i}.XData(index+1:end)];
                                        app.Plots{i}.YData = [app.Plots{i}.YData(1:index-1) app.Plots{i}.YData(index+1:end)];
                                    end
                                    for i=1:length(app.SmallPlots)
                                        app.SmallPlots{i}.XData = [app.SmallPlots{i}.XData(1:index-1) app.SmallPlots{i}.XData(index+1:end)];
                                        app.SmallPlots{i}.YData = [app.SmallPlots{i}.YData(1:index-1) app.SmallPlots{i}.YData(index+1:end)];
                                    end
                                    if (index-1)==length(app.PlotBars) %rightmost movable breakpoint
                                        app.PlotBars{index-1}.XData = [NaN NaN];
                                    else
                                        for i=index-1:length(app.PlotBars)-1
                                            app.PlotBars{i}.XData = app.PlotBars{i+1}.XData;
                                        end
                                    end
                                end


                                app.PatternValuesTable.Data(index,:) = [];
                            end

                            app.updatePatternBreakpoints();


                            return;%don't start the Move CallBack
                    end
            end
            app.UIFigure.WindowButtonMotionFcn = {@app.MovePointCallback, ax, hLine, index};
            app.UIFigure.WindowButtonUpFcn = {@app.ReleaseClickCallback, ax, hLine, index};
        end

        function MovePointCallback(app, src, evt, ax, line, i)
            %MOVEPOINTCALLBACK Summary of this function goes here
            %   Detailed explanation goes here

            %src is Figure Handle
            %evt is currently unused
            %line is the handle of the clicked line
            %       (pattern in y-edit mode OR breakpoint in x-edit mode)
            %
            % This callback is enabled by the LineClickCallBack and disabled
            % by the ReleaseClickCallback
            %
            %Note:
            %axis CurrentPoint reports non NaN values when outside of plot area
            %figure CurrentPoint reports values in figure pixels, not axis units
            %evt.IntersectionPoint reports NaN values when outside plot area


            if isequal(i, 'Slider')
                xc = round(ax.CurrentPoint(1,1));
                xc = max(xc, 0);
                xc = min(xc, 255);
                line.XData = [xc xc];
                app.PlotSliderSmall.XData = [xc xc];
                app.CommandEditField.Value = xc;

                %updateStim only called on release click unless in
                %Downloaded Patterns mode
                if isequal(app.StimModeSwitch.Value, 'Downloaded')
                    app.updateStim();
                end
                return;
            elseif length(i)~=1
                return
            end

            switch app.editMode
                case 'y' %line selected is a pattern line

                    yc = round(ax.CurrentPoint(1,2));
                    %limit yc between 0 and ax.YLim(2)
                    yc = max(yc, 0);
                    yc = min(yc, ax.YLim(2));


                    app.Y = [app.Y  yc];
                    %change YData for single Line
                    line.YData(i) = yc;
                    %disp(['move point value' num2str(yc)])

                    drawnow %limitrate nocallbacks

                    %update PatternValuesTable in ReleaseClick


                case 'x' %line selected is a breakpoint line
                    %disp('move breakpoint')
                    xc = round(ax.CurrentPoint(1,1));
                    if i > 1 && i < length(app.Plots{1}.XData)
                        %limit xc between neighboring breakpoint values
                        xc = max(xc, app.Plots{1}.XData(i-1)+1);
                        xc = min(xc, app.Plots{1}.XData(i+1)-1);
                        %change XData for all patterns
                        for j=1:length(app.Plots)
                            app.Plots{j}.XData(i) = xc;
                        end
                        for j=1:length(app.SmallPlots)
                            app.SmallPlots{j}.XData(i) = xc;
                        end
                        %change XData for breakpoint line
                        line.XData = [xc xc];
                    end
            end
        end

        function ReleaseClickCallback(app, src, evt, ax, line, i )
            %RELEASECLICKCALLBACK called when mouse button is released
            % responsible for disabling the mouse movment callback, so it only occurs
            % while mouse button is pressed
            %
            % src is figure handle
            % evt is currently unused
            %
            % This callback is enabled by the LineClickCallBack and disabled
            % by the ReleaseClickCallback (itself)
            src.WindowButtonMotionFcn = [];
            src.WindowButtonUpFcn = [];

            if isequal(i, 'Slider')
                app.updateStim();
                return;
            end
            %disp('Release Click')
            switch app.editMode
                case 'y'
                    yc = line.YData(i);
                    if ax==app.PWAxes
                        app.PatternValuesTable.Data(i,2) = yc;
                    elseif ax == app.PAAxes
                        app.PatternValuesTable.Data(i,3) = yc;
                    end

                    %Update Stored Pattern
                    app.updateFromPatternValuesTable();
                case 'x'
                    app.PatternValuesTable.Data(i,1) = line.XData(1);

                    %Update Stored Patterns
                    app.updatePatternBreakpoints()
            end


        end


        function updateDropDowns(app)

            %disp('updating DropDown menus')
            nodesPatterns = unique(cellfun(@(x) x.node, app.Patterns));
            nodesFile = find(cellfun(@(x) ~isempty(x), app.nnps.nodes));
            newNodes = find(~ismember(nodesPatterns, nodesFile));

            for i=newNodes'
                %force a column cell array
                if length(app.nnps.nodes)==1 && i>1
                    app.nnps.nodes = [app.nnps.nodes; {[]}];
                end
                app.nnps.nodes{i} =  struct('name', '?', 'type', 'PG4', 'notes', '');
            end

            groupsPatterns = unique(cellfun(@(x) x.group, app.Patterns));
            groupsFile = find(cellfun(@(x) ~isempty(x), app.nnps.groups));
            newGroups = find(~ismember(groupsPatterns, groupsFile));

            for i=newGroups'
                p = cellfun(@(x) x.group == i, app.Patterns);
                cmdID = unique(cellfun(@(x) x.cmdID, app.Patterns(p)));
                if length(cmdID)>1
                    warning('multiple cmdIDs found for channel patterns within the same group')
                    cmdID = cmdID(1);
                end
                %force a column cell array
                if length(app.nnps.groups)==1 && i>1
                    app.nnps.groups = [app.nnps.groups; {[]}];
                end
                app.nnps.groups{i} = struct('name', '?', 'cmdID', cmdID,'download', true, 'notes', '');
            end


            groupsAll = union(groupsPatterns, groupsFile);

            if ~isequal(groupsPatterns, unique(cellfun(@(x) x.group, app.Patterns)))
                warning('groupsPatterns should match groupsFile now, but does not');
            end

            %assign names from nnps.nodes
            nodeNames = cell(length(nodesPatterns), 1);
            for row = 1:length(nodesPatterns)
                node = nodesPatterns(row);
                if length(app.nnps.nodes)>=node && ~isempty(app.nnps.nodes{node})
                    nodeNames{row} = [num2str(node)  ' - ' app.nnps.nodes{node}.name ];
                else
                    nodeNames{row} = [num2str(node)  ' - ?'];
                end
            end

            app.NodeDropDown.Items = [{'-'}; nodeNames];
            app.NodeDropDown.ItemsData = [-1;  nodesPatterns];

            %assign names from nnps.groups
            groupNames = cell(length(groupsAll), 1);
            for row = 1:length(groupsAll)
                group = groupsAll(row);
                if ismember(group, groupsPatterns)
                    unusedStr = [];
                else
                    unusedStr = '* ';
                end
                if length(app.nnps.groups)>=group && ~isempty(app.nnps.groups{group})
                    groupNames{row} = [unusedStr num2str(group)  ' - ' app.nnps.groups{group}.name ];
                else
                    groupNames{row} = [unusedStr num2str(group)  ' - ?'];
                end

            end

            app.PatternGroupDropDown.Items = [{'-'}; {'Add/Remove Groups'}; groupNames];
            app.PatternGroupDropDown.ItemsData = [-1;  0; groupsAll];

            currentGroup = app.PatternGroupDropDown.Value;
            if currentGroup > 0
                if currentGroup > length(app.nnps.groups) || isempty(app.nnps.groups{currentGroup})
                    app.DownloadableCheckBox.Enable = false;
                else
                    app.DownloadableCheckBox.Enable = true;
                    app.DownloadableCheckBox.Value = app.nnps.groups{currentGroup}.download;
                end

            end
        end

        %called externally by groupedit
        function inUse = groupInUse(app, group)
            inUse = any(cellfun(@(x) x.group == group, app.Patterns));
        end

        %called externally by groupedit
        function changePatternsGroup(app, oldGroup, newGroup)
            for i =1:length(app.Patterns)
                if app.Patterns{i}.group == oldGroup
                    app.Patterns{i}.group = newGroup;
                end
            end
            app.Patterns = app.sortPatterns(app.Patterns);

            for i =1:length(app.ActivePatterns)
                if app.ActivePatterns{i}.group == oldGroup
                    app.ActivePatterns{i}.group = newGroup;
                end
            end
        end

        %called externally by groupedit
        function removePatternsGroup(app, group)
            i = cellfun(@(x) x.group == group, app.Patterns);
            app.Patterns(i)=[];

            %if active group  is the group being deleted, force the drop
            %down menu back to none selected
            if app.PatternGroupDropDown.Value == group
                app.PatternGroupDropDown.Value = -1;
                app.PatternGroupDropDownValueChanged([]);
            end
        end

        %called externally by groupedit
        function duplicatePatternsGroup(app, oldGroup, newGroup)
            p =  app.Patterns(cellfun(@(x) x.group == oldGroup, app.Patterns));
            for i=1:length(p)
                p{i}.group = newGroup;
            end
            app.Patterns = [app.Patterns; p];
            app.Patterns = app.sortPatterns(app.Patterns);
        end

        %called externally by groupedit
        function addNewPatterns(app, newPatterns)
            app.Patterns = [app.Patterns; newPatterns];
            app.Patterns = app.sortPatterns(app.Patterns);
        end

        %called externally by groupedit
        function changePatternsCmdID(app, group, cmdID)
            iGroup =  find(cellfun(@(x) x.group == group, app.Patterns));
            for i=iGroup'
                app.Patterns{i}.cmdID = cmdID;
            end
        end

        function updateChannelName(app)
            if isequal(app.viewMode, 'patterngroup')
                app.PatternGroupDropDownValueChanged([]);
            else
                app.NodeDropDownValueChanged([]);
            end
        end

        %called externally by nodeedit
        function nPatterns = getPatternCount(app, node)
            nPatterns = sum(cellfun(@(x) x.node == node, app.Patterns));
        end


        function  unloadPatterns(app)
            app.PatternGroupDropDown.Value = -1;
            app.NodeDropDown.Value = -1;
            app.PatternGroupDropDownValueChanged([]);
        end
        
        function OpenFileByName(app,filename,filepath)
            ld = load(fullfile(filepath, filename));
            TempFile = fullfile(filepath, filename);  % save filename as temporary until the file is checked

            fields = {'patterns', 'channels', 'nodes', 'groups'};
            iMissing = find(~isfield(ld, fields));

            str = [];
            for i=1:length(iMissing)
                str = [str ', ' fields{iMissing(i)}];
            end
            if ~isempty(str)
                warndlg(['Invalid NNP System File.  Does not contain variable(s): ' str '.'], 'Open File')
                return;
            end
            %               If the file is invalid, we don't want to update anything, including the file name
            app.saveFile = TempFile;
            app.Patterns = ld.patterns;
            app.nnps.channels = ld.channels;
            app.nnps.nodes = ld.nodes;
            app.nnps.groups = ld.groups;
            app.updateDropDowns();
            figure(app.UIFigure);
        end
    end

    methods (Access = private)

        %y is a cell array
        function initAxes(app, ax, breakpoints, y, maxY, main)
            nMaxBreakPoints=20;
            nPatterns = length(y);

            ax.Visible = 'off';

            cla(ax);
            hold(ax,'on');


            if main
                app.MinMaxPatch = patch(app.PWAxes, [0 255 255 0 0 255 255 0], [0 0 0 0 255 255 255 255],...
                    [.7 .7 .7], 'EdgeColor', 'none', 'FaceAlpha', 0.5);

                app.PlotSlider = plot(ax, [0 0], [0 maxY], 'LineWidth', 4, 'Color', [1, 0.5, 0]);

                app.Plots = cell(nPatterns,1);
                for i=1:nPatterns
                    if isequal(app.viewMode, 'patterngroup')
                        x = breakpoints;
                    else
                        x = breakpoints{i};
                    end
                    app.Plots{i} = plot(ax, x, y{i});
                end

                if isequal(app.viewMode, 'patterngroup')
                    %we will only change XData and YData in the callback functions so we need
                    %to have all the handles to possible plots already assigned.  Since
                    %breakpoints can be added and removed, we will already allocate all 20
                    %possible breakpoints, and just use NaNs as x values to make them
                    %invisible.  We won't draw vertical bars for the first and last breakpoints
                    app.PlotBars = cell(nMaxBreakPoints-2,1);
                    for i=2:nMaxBreakPoints-1
                        if i<length(breakpoints)
                            x = [breakpoints(i) breakpoints(i)];
                        else
                            x = [NaN NaN];
                        end
                        app.PlotBars{i-1} = plot(ax, x, [0 maxY]);
                    end
                end

            else
                app.PlotSliderSmall = plot(ax, [0 0], [0 maxY], 'LineWidth', 4, 'Color', [1, 0.5, 0]);

                app.SmallPlots = cell(nPatterns,1);
                for i=1:nPatterns
                    if isequal(app.viewMode, 'patterngroup')
                        x = breakpoints;
                    else
                        x = breakpoints{i};
                    end
                    app.SmallPlots{i} = plot(ax, x, y{i});
                end

            end
            hold(ax,'off');

            axis(ax, [0 255 0 maxY]);
            %xlabel(ax, ['Command (' num2str(cmdID) ')']);
            if maxY == 255
                ylabel(ax, 'Pulse Width (us)')
            else
                ylabel(ax, 'Amplitude (mA/10)')

            end

            %title(ax, ['Group' num2str(group)])
            ax.XMinorTick = 'on';
            ax.YMinorTick = 'on';
            ax.XGrid = 'on';
            ax.YGrid = 'on';
            ax.XMinorGrid = 'on';
            ax.YMinorGrid = 'on';
            ax.Toolbar.Visible = 'off';  %make the little toolbar at top right disappear.
            ax.Interactions = [dataTipInteraction];

            if main

                for i=1:length(app.Plots)
                    m = floor((i-1)/8)+1; %marker index
                    c = mod((i-1),8)+1;      %color index
                    app.Plots{i}.ButtonDownFcn = {@app.LineClickCallback};
                    app.Plots{i}.Marker = app.markerOrder(m);
                    app.Plots{i}.Color = app.colorOrder(c,:);
                    app.Plots{i}.MarkerFaceColor = app.Plots{i}.Color;
                    app.Plots{i}.MarkerEdgeColor = 'none';%app.Plots{i}.Color;
                    app.Plots{i}.LineWidth = 2;
                    %app.Plots{i}.HitTest = 'on';
                end

                if isequal(app.viewMode, 'patterngroup')
                    for i=1:length(app.PlotBars)
                        app.PlotBars{i}.ButtonDownFcn = {@app.LineClickCallback};
                        app.PlotBars{i}.LineWidth = 2;
                        app.PlotBars{i}.Color = 'k';
                    end
                end

                app.PlotSlider.ButtonDownFcn = {@app.SliderClickCallback};
            else
                for i=1:length(app.SmallPlots)

                    app.SmallPlots{i}.ButtonDownFcn = {@app.LineClickCallback};
                    app.SmallPlots{i}.Marker = app.Plots{i}.Marker;
                    app.SmallPlots{i}.Color = app.Plots{i}.Color;
                    app.SmallPlots{i}.MarkerFaceColor = app.SmallPlots{i}.Color;
                    app.SmallPlots{i}.MarkerEdgeColor = 'none';
                    app.SmallPlots{i}.LineWidth = 2;
                end
            end

            EditModeSwitchValueChanged(app, 0);


            %disable tooltips on graphs
            %ShowToolTipsCheckBoxValueChanged(app, 0);
            disableDefaultInteractivity(app.PWAxes);
            disableDefaultInteractivity(app.PAAxes);

            ax.Visible = 'on';


        end

        function setActivePatterns(app, p)
            app.ActivePatterns = app.Patterns(p);

            n = size(app.ActivePatterns,1); %number of active patterns
            d = cell(n,10);
            for i=1:n
                group = app.ActivePatterns{i}.group;
                node = app.ActivePatterns{i}.node;
                ch = app.ActivePatterns{i}.ch;
                if group > length(app.nnps.groups) || isempty(app.nnps.groups{group})
                    d{i, 1} = ['#' num2str(group) ' : ?' ]; %group name
                else
                    d{i, 1} = ['#' num2str(group) ' : ' app.nnps.groups{group}.name]; %group name
                end

                d{i, 2} = node;
                d{i, 3} = ch;
                d{i, 4} = app.ActivePatterns{i}.cmdID;
                d{i, 7} = false; %Mute

                if node > size(app.nnps.channels,1) || ch > size(app.nnps.channels,2) ||...
                        isempty(app.nnps.channels{node, ch})
                    d{i, 5} = 0;
                    d{i, 6} = 255;
                    d{i,10} = '?';
                else
                    d{i, 5} = app.nnps.channels{node,ch}.min;
                    d{i, 6} = app.nnps.channels{node,ch}.max;
                    d{i,10} = app.nnps.channels{node, ch}.name; %muscle name
                end
            end

            app.PatternTable.Data = d;

            d = cell(n,1);
            for i=1:n
                m = floor((i-1)/8)+1;
                d{i} = app.markerOrderLabel(m);
            end
            app.ColorTable.Data = d;
            app.ColorTable.BackgroundColor = app.colorOrder;

            cmd = cellfun(@(x)x.cmd,app.ActivePatterns, 'UniformOutput', false);
            pw = cellfun(@(x)x.pw,app.ActivePatterns, 'UniformOutput', false);
            amp = cellfun(@(x)x.amp,app.ActivePatterns, 'UniformOutput', false);
            if length(pw)~=length(cmd) || length(amp)~=length(cmd)
                msgbox(['Patterns corrupted: cmd/pw/amp must exist for each pattern.  Check patterns: ' num2str(find(p)')], 'Pattern Editor', 'modal')
                app.unloadPatterns();
                return
            end
            for i =1:length(cmd)
                if length(cmd{i})~=length(pw{i}) || length(cmd{i})~=length(amp{i})
                    msgbox(['Patterns corrupted: cmd/pw/amp must be matching length for each pattern.  Check patterns: ' num2str(find(p)')], 'Pattern Editor', 'modal')
                    app.unloadPatterns();
                    return
                end
            end
            if isequal(app.viewMode, 'patterngroup')
                breakpoints = unique(cell2mat(cmd), 'rows');

                if size(breakpoints, 1) > 1
                    msgbox(['All plotted patterns must have same breakpoints.  Check patterns: ' num2str(find(p)')], 'Pattern Editor', 'modal')
                    app.unloadPatterns();
                    return
                end
            else
                breakpoints = cmd;
            end
            %initAxes(app, ax, breakpoints, y, maxY, main)
            app.initAxes(app.PWAxes, breakpoints, pw, 255, true);
            app.initAxes(app.PAAxes, breakpoints, amp, 200, false);

            app.updateStim(); %call to set interpolated values in PW and Amp columns after plots have been generated
        end


        function updateActivePattern(app, row)

            if row < 1 || row > length(app.ActivePatterns)
                return;
            end

            %disp('UpdateActivePattern')
            %highlight active pattern in PatternTable
            app.activePatternRow = row;
            bg = app.UnselectedColor;
            bg(row,:) = [1 1 0]; %yellow
            app.PatternTable.BackgroundColor = bg;

            app.PatternValuesTable.Data = [app.ActivePatterns{row}.cmd
                app.ActivePatterns{row}.pw
                app.ActivePatterns{row}.amp]';

            %set all linewidths to normal
            for i=1:size(app.Plots, 1)
                app.Plots{i}.LineWidth = 2;
                app.Plots{i}.MarkerSize = 6;
                app.SmallPlots{i}.LineWidth = 2;
                app.SmallPlots{i}.MarkerSize = 6;
            end
            %set selected pattern linewidth/marker size
            app.Plots{row}.LineWidth = 4;
            app.Plots{row}.MarkerSize = 12;
            app.SmallPlots{row}.LineWidth = 4;
            app.SmallPlots{row}.MarkerSize = 12;

            %Update Min/Max
            node = app.ActivePatterns{row}.node;
            ch   = app.ActivePatterns{row}.ch;
            cmdID   = app.ActivePatterns{row}.cmdID;
            min = app.PatternTable.Data{row,5};
            max = app.PatternTable.Data{row,6};
            app.MinMaxPatch.YData = [0 0 min min max max 255 255];


            %move active plot above others so easier to click
            moveToTop(app, app.PWAxes, app.Plots{row});
            moveToTop(app, app.PAAxes, app.SmallPlots{row});
            %moveToTop(app, app.PWAxes, app.PlotSlider);
            %moveToTop(app, app.PWAxes, app.PlotSliderSmall);

            %Retitle Plot with Active Pattern Group and selected Channel Pattern
            currentGroup = app.PatternGroupDropDown.Value;


            switch app.viewMode
                case 'patterngroup'
                    if currentGroup > length(app.nnps.groups) || isempty(app.nnps.groups{currentGroup})
                        app.PWAxes.Title.String = ['Pattern Group #' num2str(currentGroup) ' : ? ' ...
                            ' - ' app.PatternTable.Data{row, 10}];
                    else
                        app.PWAxes.Title.String = ['Pattern Group #' num2str(currentGroup) ' : ' app.nnps.groups{currentGroup}.name ...
                            ' - ' app.PatternTable.Data{row, 10}];
                    end
                    app.MuscleEditField.Value =app.PatternTable.Data{row, 10};
% CHECK - sometimes these are read as strings, other times as numbers.
% They need to be numbers.  It is due to how Matlab now handles tables. Is
% there a way to determine whether a value is a string or number?
% isnumeric()
                    if isnumeric(app.PatternTable.Data{row, 8})
                        app.PWEditField.Value = app.PatternTable.Data{row, 8};
                    else
                    app.PWEditField.Value =str2num(app.PatternTable.Data{row, 8});
                   end
                case 'node'
                    app.PWAxes.Title.String = ['Node #' num2str(node) ', Channel #' num2str(ch) ...
                        ' - ' app.PatternTable.Data{row, 10}];

            end


            app.CommandLabel.Text = ['Command (' num2str(cmdID) ')'];

        end


        function moveToTop(app, axes, line)
            %move_to_bottom Moves the desired object to the bottom of the stack.
            %   For use with appdesigner objects where uistack no longer works.


            all_handles = axes.Children;

            if (numel(all_handles) < 2)
                return
            end

            all_indices = (1:numel(all_handles))';

            for index = 1:numel(line)
                if ishandle(line(index))
                    this_index = find(all_handles == line(index));

                    if ~isempty(this_index)
                        rest_of_indices = setdiff(all_indices, this_index);
                        all_handles = all_handles([this_index; rest_of_indices]);
                    end
                end
            end

            axes.Children = all_handles;
        end

        function clearActivePatterns(app)
            app.ActivePatterns = {};
            if isequal(app.viewMode, 'patterngroup')
                breakpoints = [0 255];
            else
                breakpoints = {[0 255]};
            end
            app.initAxes(app.PWAxes, breakpoints, {[NaN NaN]}, 255, true);
            app.initAxes(app.PAAxes, breakpoints, {[NaN NaN]}, 200, false);

            app.PatternTable.Data = {};
            app.PatternValuesTable.Data = [];
            app.ColorTable.Data = {};

            app.PWAxes.Title.String = 'No Pattern Group/Node Selected or no patterns available';

        end

        %updates ActivePatterns and Patterns with changes made in PatternValuesTable
        function updateFromPatternValuesTable(app)
            %disp('Updating Stored Pattern - selected channel pattern');

            i = app.activePatternRow;
            group = app.ActivePatterns{i}.group;
            node = app.ActivePatterns{i}.node;
            ch = app.ActivePatterns{i}.ch;


            app.ActivePatterns{i}.n = size(app.PatternValuesTable.Data, 1);
            app.ActivePatterns{i}.cmd = app.PatternValuesTable.Data(:,1)';
            app.ActivePatterns{i}.pw = app.PatternValuesTable.Data(:,2)';
            app.ActivePatterns{i}.amp = app.PatternValuesTable.Data(:,3)';

            p = cellfun(@(x) x.group == group && x.node == node && x.ch == ch, app.Patterns);
            if sum(p)==1
                app.Patterns{p}.n = app.ActivePatterns{i}.n;
                app.Patterns{p}.cmd = app.ActivePatterns{i}.cmd;
                app.Patterns{p}.pw = app.ActivePatterns{i}.pw;
                app.Patterns{p}.amp = app.ActivePatterns{i}.amp;
            else
                warning('could not find stored pattern matching group/node/ch')
            end

            app.updateStim();
        end

        function updatePatternBreakpoints(app)
            %disp('Updating all Active Patterns - breakpoint change');


            for i=1:length(app.ActivePatterns)
                cmd = app.Plots{i}.XData;
                pw = app.Plots{i}.YData;
                amp = app.SmallPlots{i}.YData;

                app.ActivePatterns{i}.n = length(cmd);
                app.ActivePatterns{i}.cmd = cmd;
                app.ActivePatterns{i}.pw = pw;
                app.ActivePatterns{i}.amp = amp;

                group = app.ActivePatterns{i}.group;
                node = app.ActivePatterns{i}.node;
                ch = app.ActivePatterns{i}.ch;

                p = cellfun(@(x) x.group == group && x.node == node && x.ch == ch, app.Patterns);
                if sum(p)==1
                    app.Patterns{p}.n = app.ActivePatterns{i}.n;
                    app.Patterns{p}.cmd = app.ActivePatterns{i}.cmd;
                    app.Patterns{p}.pw = app.ActivePatterns{i}.pw;
                    app.Patterns{p}.amp = app.ActivePatterns{i}.amp;
                else
                    warning('could not find stored pattern matching group/node/ch')
                end
            end

            app.updateStim();
        end


        function updateStim(app)
            %If stim is off, we still want to show the interpolated PW, and amp value
            %
            % If muted or stim off, show value in (XXX)
            % If in download pattern mode use XXX* to indicate that value is assumed from displayed patterns
            %
            % Ideally we would show this by graying out the value, but uitables don't allow unique font or
            % background colors for individual cells
            % Further, ideally we would somewhere show the actual PW/PA values reported by the PG (3213), but
            % this would require additional radio reads and slow down update rates
               
            data = app.PatternTable.Data;
            cmd = app.CommandEditField.Value;
            for row = 1:length(app.ActivePatterns)
                pw = round(interp1(app.Plots{row}.XData, app.Plots{row}.YData, cmd));
                amp = round(interp1(app.SmallPlots{row}.XData, app.SmallPlots{row}.YData, cmd));

                if (row==app.activePatternRow)
                    app.PWEditField.Value=pw;
                end
                
                if app.stimOn && isequal(app.StimModeSwitch.Value, 'Displayed') && ~data{row, 7} %muted
                    data{row, 8} = pw;
                    data{row, 9} = amp;
                elseif app.stimOn && isequal(app.StimModeSwitch.Value, 'Downloaded')
                    data{row, 8} = [num2str(pw) '*'];
                    data{row, 9} = [ num2str(amp) '*'];
                else
                    data{row, 8} = ['(' num2str(pw) ')'];
                    data{row, 9} = ['(' num2str(amp) ')'];
                end
%                if (row==app.activePatternRow)
%                      app.PWEditField.Value =str2num(data{row, 8});
%                     app.PWEditField.Value =app.PatternTable.Data{row, 8};
%                end
            end
            app.PatternTable.Data = data;
% $$ set displayed PW here?

            if ~app.stimOn
                return
            end

            %disp('Updating Stim')
            app.updatingStim = true;

            if isequal(app.StimModeSwitch.Value, 'Displayed')


                if app.HighBandwidthCheckBox.Value == true
                    % Need to update Stim array in PM OD
                    %JML NOTE: currently supports nodes #1-6
                    % Doesn't currently support multiple instances of App,
                    % because it sets unused stim channels to 0 PW/amp

                    stimArray = zeros(1, 48);
               
                    for row = 1:length(app.ActivePatterns)
                        node = data{row, 2};
                        ch = data{row, 3};
                        pw = data{row, 8};
                        amp = data{row, 9};
                        if ~isnumeric(pw)
                            pw = 0;
                            amp = 0;
                        end
                        if node > 6
                            warning('App does not currently support node # higher than 6 for High Bandwidth Stim Mode')
                        end

                        i = (node-1)*8+(ch-1)*2+1;
                        stimArray(i:i+1) = [pw amp];
                    end
                    sinceLast=toc(app.lastStimUpdate);
                    if sinceLast>app.stimUpdateLimit
                          app.lastStimUpdate=tic; % mark new baseline
                        x=tic;
                          resp = app.nnp.write(7, '3021', 1, stimArray, 'uint8', 48);
                        xtoc=toc(x);
%                      resp=0;
                        if ~isequal(resp, 0)
%                             sL=sprintf('%3.5f ',sinceLast);
%                              xt=sprintf('x %3.5f ss %d',xtoc,app.stepSize);
%                             app.ErrorsTextArea.Value = [[sL  xt 'Could not send Stim Array : ' app.nnp.lastError]; app.ErrorsTextArea.Value];
                            app.ErrorsTextArea.Value = [['Could not send Stim Array : ' app.nnp.lastError]; app.ErrorsTextArea.Value];
                        else
%                             sL=sprintf('%3.5f ',sinceLast);
%                              xt=sprintf('x %3.5f ss %d',xtoc,app.stepSize);
%                              app.ErrorsTextArea.Value = [[sL xt]; app.ErrorsTextArea.Value];
                        end
%                          app.lastStimUpdate=tic; % mark new baseline
                    end
                else
                    % Need to update OD entries for each involved PG
                    for row = 1:length(app.ActivePatterns)
                        node = data{row, 2};
                        ch = data{row, 3};
                        pw = data{row, 8};
                        amp = data{row, 9};
                        if ~isnumeric(pw)
                            pw = 0;
                            amp = 0;
                        end 
                        resp = app.nnp.write(node, '3212', ch, [pw amp], 'uint8');
                        if ~isequal(resp, 0)
                            app.ErrorsTextArea.Value = [['Could not update Stim, node ' num2str(node), ' ch ' num2str(ch) ' : ' app.nnp.lastError]; app.ErrorsTextArea.Value];
                        end
                    end
                end
                
            else %Using Downloaded Patterns
                % Just need to update Command in PM OD
                cmdID   = app.ActivePatterns{app.activePatternRow}.cmdID;
                %read the current command array in PM OD, so we can change
                %only the active cmdID.  This way multiple instances can
                %of the app can be used to activate multiple patterns at
                %once
%                 cmdArray = app.nnp.read(7, '3020', 1, 8, 'uint8');
%                 if length(cmdArray)==8
%                     cmdArray(cmdID) = cmd;
%                     resp = app.nnp.write(7, '3020', 1, cmdArray, 8, 'uint8');
%                     if ~isequal(resp, 0)
%                         app.ErrorsTextArea.Value = [['Could not set command : ' app.nnp.lastError]; app.ErrorsTextArea.Value];
%                     end
%                 else
%                     app.ErrorsTextArea.Value = [['Could not read command : ' app.nnp.lastError]; app.ErrorsTextArea.Value];
%                 end
                cmdArray=zeros(1,8);
                cmdArray(cmdID) = cmd;
                resp = app.nnp.write(7, '3020', 1, cmdArray, 8, 'uint8');
                if ~isequal(resp, 0)
                    app.ErrorsTextArea.Value = [['Could not set command : ' app.nnp.lastError]; app.ErrorsTextArea.Value];
                end
            end
            
            app.updatingStim = false;
        end

        function enableUIStim(app, enable)
            app.PatternGroupDropDown.Enable = enable;
            app.NodeDropDown.Enable = enable;
            app.AddChannelPatternButton.Enable = enable;
            app.RemoveChannelPatternButton.Enable = enable;
            app.StimModeSwitch.Enable = enable;
            app.HighBandwidthCheckBox.Enable = enable;
        end

        function enableUIPatternGroup(app, enable)
            app.EditModeSwitch.Enable = enable;
            app.AddChannelPatternButton.Enable = enable;
            app.RemoveChannelPatternButton.Enable = enable;
            app.StimONButton.Enable = enable;
            app.StimOFFButton.Enable = enable;
            app.StimModeSwitch.Enable = enable;
            app.HighBandwidthCheckBox.Enable = enable;
            app.DownloadableCheckBox.Visible = enable;
            app.PatternValuesTable.ColumnEditable = [enable, true, true];
        end


        function nodes = getActiveNodesForDownload(app)
            nodeModes = app.nnp.getStatus();
            nodesReady = find(cellfun(@(x) isequal(x, 'Waiting'), nodeModes));

            if app.CurrentOnlyCheckBox.Value
                if isequal(app.viewMode, 'node')
                    %Erase only current node
                    nodes = app.NodeDropDown.Value;
                else
                    %Erase only nodes involved in active patterns
                    nodes = unique(cellfun(@(x) x.node, app.ActivePatterns));
                    if ~app.DownloadableCheckBox.Value
                        resp = questdlg('Current pattern group is not set as downloadable, do you want to proceed with this pattern anyway?');
                        if ~isequal(resp, 'Yes')
                            nodes = [];
                            return;
                        end
                    end
                end
            else %Erase all nodes involved in downloadable patterns
                groups = find(cellfun(@(x) ~isempty(x) && x.download, app.nnps.groups)); %downloadable groups
                d = cellfun(@(x) ismember(x.group, groups), app.Patterns); %downloadable patterns
                nodes = unique(cellfun(@(x) x.node, app.Patterns(d))); %nodes for those patterns
            end

            nodesNotReady = nodes((~ismember(nodes, nodesReady)));
            if ~isempty(nodesNotReady)
                if isequal(nodes, nodesNotReady)
                    warndlg(['No nodes (' num2str(nodesNotReady') ') associated with downloadable patterns are available. Check that nodes are in waiting mode and try again'], 'modal');
                    nodes = [];
                    return;
                else
                    resp = questdlg(['Some nodes (' num2str(nodesNotReady') ') associated with downloadable patterns are not available. Do you want to proceed and skip these nodes? Otherwise check that nodes are in waiting mode and try again']);
                    if ~isequal(resp, 'Yes')
                        nodes = [];
                        return;
                    else
                        nodes = nodes((ismember(nodes, nodesReady)));
                    end
                end
            end
        end

        function p = sortPatterns(app, p)
            %re-sort all patterns by group, node, ch
            M = [cellfun(@(x) x.group, p), ...
                cellfun(@(x) x.node, p), ...
                cellfun(@(x) x.ch, p)];
            [~,i] = sortrows(M);
            p = p(i);
        end

        function updatePMCommand(app, obj, event)
            %Polls PM for new command instead of using slider position and
            %then updates the slider position which triggers a stim command
            %update
            if ~app.stimOn
                return;
            end

            resp = app.nnp.read(7, '1f53', app.SubindexEditField.Value);
            if isempty(resp)
                app.ErrorsTextArea.Value = [['Could not read PM Command: ' app.nnp.lastError]; app.ErrorsTextArea.Value];
            else
                app.CommandEditField.Value = double(resp);
                app.CommandEditFieldValueChanged([])
            end
            drawnow

        end

        function processUpDownKey(app,key)
            %from testKeyRepeat
%             %if app.clearPressed || app.ctrlPressed
             if app.insertPressed || app.ctrlPressed
                ss=app.stepSize*app.stepSizeMult;
            else
                ss=app.stepSize;
            end
           
            switch key
                case 'uparrow'
                    unit=ss; % increasing
                case 'downarrow'
                    unit=-ss; % decreasing
            end

            %don't allow pattern value changes in Downladed stim mode becuase they will not have an immediate effect
            if isequal(app.StimModeSwitch.Value, 'Displayed')
                i = find(app.Plots{app.activePatternRow}.XData == app.PlotSlider.XData(1), 1);
                if ~isempty(i)
                    if app.altPressed
                        yc = max(min(app.SmallPlots{app.activePatternRow}.YData(i) + unit, 200),0);
                        app.SmallPlots{app.activePatternRow}.YData(i) = yc;
                        %disp('increase PA')
                        app.PatternValuesTable.Data(i,3) = yc;
                    else
                        yc = max(min(app.Plots{app.activePatternRow}.YData(i) + unit, 255),0);
                        app.Plots{app.activePatternRow}.YData(i) = yc;
                        %disp('increase PW')
                        app.PatternValuesTable.Data(i,2) = yc;
                    end
                    app.updateFromPatternValuesTable();
                end
                app.stepSize=app.littleStep; % always set back to little
            end
    end
        
        function results = overrideNumLock(app,key)
            % Override the numlock by converting the numpad keys to the
            % non-lock values
            switch key
                case 'numpad0'
                    results='insert';
                case 'numpad1'
                    results='end';
                case 'numpad2'
                    results='downarrow';
                case 'numpad3'
                    results='pagedown';
                case 'numpad4'
                    results='leftarrow';
                case 'numpad5'
                    results='clear';
                case 'numpad6'
                    results='rightarrow';
                case 'numpad7'
                    results='home';
                case 'numpad8'
                    results='uparrow';
                case 'numpad9'
                    results='pageup';
                case 'decimal'
                    results='delete';
                case 'backspace'
                    results='leftbracket';
                case 'divide'
                    results='rightbracket';
                otherwise
                    results=key;
            end
        end
        
        function MuteToggleCurrentRow(app)
            % read the mute status of the current row
            row=app.activePatternRow;

            mute=app.PatternTable.Data{row,7};
            mute=not(mute);
            % mute or unmute based on status (change pattern table data)
            app.PatternTable.Data{row,7}=mute;

            % replot based on mute status
            if mute
                %Muted (dashed)
                app.Plots{row}.LineStyle = ':';
                app.SmallPlots{row}.LineStyle = ':';
            else
                %Unmuted (solid)
                app.Plots{row}.LineStyle = '-';
                app.SmallPlots{row}.LineStyle = '-';
            end
            % update stim
            app.updateStim();
        end
end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
        if nargin<2
            NNP=[];
        else
            NNP=varargin{1};
        end
         useNNP=true;
        if isempty(NNP)  || ~isvalid(NNP) %if being called after nnp cancelled from another app
            app.nnp = NNPAPI();
            if isempty(app.nnp.port)
%                 msgbox('No port chosen for NNP Access Point')
                 uialert(app.UIFigure,'No port chosen for NNP Access Point','No NNP AP')
                delete(app.nnp)
                 figure(app.UIFigure); %return focus
                 useNNP=false;
%                 return
            end
        else
            app.nnp = NNP;
        end
        if nargin>2
            app.callingFigure=varargin{2};
        end
        if nargin>3  % third is userFolders
            app.userFolders=varargin{3};  % userfiles, code, user, config, data, log
            app.UserIDEditField.Value=app.userFolders.code;
        end

        app.clearActivePatterns();

        %app.PatternTable.CellSelectionCallback={@app.CellSelectionCallback};
        stripe = [ 1.0000    1.0000    1.0000
            0.9400    0.9400    0.9400];
        app.PatternTable.BackgroundColor = repmat(stripe, 24, 1); %support up to 48 rows in table
        app.UnselectedColor = app.PatternTable.BackgroundColor;

        app.UIFigure.GraphicsSmoothing = 'on';

        %app.BreakpointsLabel.Visible = false;
        app.EditModeSwitch.Value = 'Pattern Values';
        app.enableUIPatternGroup(false);
        app.NodeDropDownValueChanged([]);

        %align right the Muscle Name - Not easy to align center
        app.PatternTable.ColumnFormat = {'', '', '', '', '', '',  '', 'numeric', 'numeric', 'numeric'};
if useNNP
        app.NewMenuSelected([]);
        app.nnp.enterWaiting(); % must be in waiting to start network
        app.nnp.networkOn(); % Start the network  when opening pattern edit because it is needed

        app.pmCommandTimer = timer;
%        app.pmCommandTimer.Period = 2;
         app.pmCommandTimer.Period = 0.2;
        app.pmCommandTimer.StartDelay = app.pmCommandTimer.Period;
        app.pmCommandTimer.ExecutionMode = 'fixedDelay';
        app.pmCommandTimer.BusyMode = 'drop';
        app.pmCommandTimer.TimerFcn = @app.updatePMCommand;
end
        app.PatternValuesTable.Data = cell(2,3); %Initialize table
        app.lastUpdate=tic;%initialize tic
        app.lastStimUpdate=tic; % initialize tic

        if isfield(app.userFolders,'config') && ~isempty(app.userFolders.config)
            filename=[app.userFolders.code,app.defaultSuffix];
            app.defaultName=filename; % this is reduntant, could combine above
            filepath=app.userFolders.config;
            app.defaultPath=filepath;
            app.defaultProfileName=[app.userFolders.code,app.defaultProfileSuffix];
            fullName=fullfile(filepath,filename);
            if exist(fullName,'file')==2  % make sure file exists before trying to open
                app.OpenFileByName(filename,filepath);
            end
        end

        end

        % Value changed function: EditModeSwitch
        function EditModeSwitchValueChanged(app, event)
        if isequal(app.EditModeSwitch.Value, 'Breakpoints')
%             app.BreakpointsLabel.Visible = true;
            %disp('Edit X')
            app.editMode = 'x';
            for i=1:length(app.PlotBars)
                app.PlotBars{i}.LineStyle = ':';
                app.PlotBars{i}.HitTest = 'on';
            end
            for i=1:length(app.Plots)
                app.Plots{i}.HitTest = 'on';
            end
            for i=1:length(app.SmallPlots)
                app.SmallPlots{i}.HitTest = 'off';
            end
        else
            %app.BreakpointsLabel.Visible = false;
            %disp('Edit Y')
            app.editMode = 'y';
            if isequal(app.viewMode, 'patterngroup')
                for i=1:length(app.PlotBars)
                    app.PlotBars{i}.LineStyle = 'none';
                    app.PlotBars{i}.HitTest = 'off';
                end
            end
            for i=1:length(app.Plots)
                app.Plots{i}.HitTest = 'on';
            end
            for i=1:length(app.SmallPlots)
                app.SmallPlots{i}.HitTest = 'on';
            end
        end
        end

        % Callback function
        function ShowToolTipsCheckBoxValueChanged(app, event)
        if app.ShowToolTipsCheckBox.Value
            enableDefaultInteractivity(app.PWAxes);
            enableDefaultInteractivity(app.PAAxes);
        else
            disableDefaultInteractivity(app.PWAxes);
            disableDefaultInteractivity(app.PAAxes);
        end

        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
        if  ~isempty(app.Patterns) || ~isempty(app.nnps) && (~isempty(app.nnps.channels) || ~isempty(app.nnps.nodes) || ~isempty(app.nnps.groups))
            resp = questdlg('You may have unsaved changes.  Do you want to continue with a new file?');
            if ~isequal(resp, 'Yes')
                return;
            end
        end
         %force change back to no selected pattern
         % if this is empty already, cannot force value to -1
         if ~isempty(app.PatternGroupDropDown.Value)
            app.PatternGroupDropDown.Value = -1;      
            app.PatternGroupDropDownValueChanged([]);
         end
         % the above will force user to select a pattern and not leave prior pattern from previous file on display   
        [filename, filepath] = uigetfile('.mat', 'Select NNP System File',app.defaultName);
        if filename
            app.OpenFileByName(filename, filepath);
        end
        %{
        if filename
            ld = load([filepath filename]);
            TempFile = [filepath filename];  % save filename as temporary until the file is checked

        else
            return;
        end
        fields = {'patterns', 'channels', 'nodes', 'groups'};
        iMissing = find(~isfield(ld, fields));

        str = [];
        for i=1:length(iMissing)
            str = [str ', ' fields{iMissing(i)}];
        end
        if ~isempty(str)
            warndlg(['Invalid NNP System File.  Does not contain variable(s): ' str '.'], 'Open File')
            return;
        end
        %               If the file is invalid, we don't want to update anything, including the file name
        app.saveFile = TempFile;
        app.Patterns = ld.patterns;
        app.nnps.channels = ld.channels;
        app.nnps.nodes = ld.nodes;
        app.nnps.groups = ld.groups;
        app.updateDropDowns();
        figure(app.UIFigure);
        %}
        end

        % Button pushed function: ButtonUp
        function ButtonUpPushed(app, event)
        app.PatternTable.Position(2) = max(app.PatternTable.Position(2)-100, -636);
        app.ColorTable.Position(2) = app.PatternTable.Position(2);
        end

        % Button pushed function: ButtonDown
        function ButtonDownPushed(app, event)
        app.PatternTable.Position(2) = min(app.PatternTable.Position(2)+100, 0);
        app.ColorTable.Position(2) = app.PatternTable.Position(2);
        end

        % Button pushed function: ButtonTop
        function ButtonTopPushed(app, event)
        app.PatternTable.Position(2) = -636;
        app.ColorTable.Position(2) = app.PatternTable.Position(2);
        end

        % Button pushed function: ButtonBottom
        function ButtonBottomPushed(app, event)
        app.PatternTable.Position(2) = 0;
        app.ColorTable.Position(2) = app.PatternTable.Position(2);
        end

        % Value changed function: PatternGroupDropDown
        function PatternGroupDropDownValueChanged(app, event)
        currentGroup = app.PatternGroupDropDown.Value;
        app.CommandEditField.Value = 0; %Set command level to 0 since the slider will get replotted at 0

        if currentGroup == -1
            app.clearActivePatterns();
            app.enableUIPatternGroup(false);
            return;
        elseif currentGroup == 0 %Open Group Editor
            app.GroupsMenuSelected([]);
            %force change back to no selected pattern
            app.PatternGroupDropDown.Value = -1;
            app.PatternGroupDropDownValueChanged([]);
            return;
        else
            app.NodeDropDown.Value = -1;
            app.viewMode = 'patterngroup';

            app.CurrentOnlyCheckBox.Text = 'Current Pattern Group Only';

            %add functionality available only in "patterngroup" view mode
            app.enableUIPatternGroup(true);
        end

        if currentGroup > length(app.nnps.groups) || isempty(app.nnps.groups{currentGroup})
            app.DownloadableCheckBox.Enable = false;
        else
            app.DownloadableCheckBox.Enable = true;
            app.DownloadableCheckBox.Value = app.nnps.groups{currentGroup}.download;
        end

        p = cellfun(@(x) x.group == currentGroup, app.Patterns);
        app.setActivePatterns(p);

        if any(p) && ~isempty(app.PatternTable.Data )
            app.updateActivePattern(1);  %default to first row in table is active
        else
            app.clearActivePatterns();
        end
        app.PatternGroupDropDown.Enable='off'; % stop key focus by disabling
        pause(1);
        app.PatternGroupDropDown.Enable='on'; % enable again
%          figure(app.UIFigure); % focus on figure
        end

        % Value changed function: NodeDropDown
        function NodeDropDownValueChanged(app, event)
        currentNode = app.NodeDropDown.Value;
        if isempty(currentNode)
            currentNode=-1;
        end
        app.CommandEditField.Value = 0; %Set command level to 0 since the slider will get replotted at 0

        if currentNode == -1
            app.clearActivePatterns();
            dmessage='';
             app.ErrorsTextArea.Value = dmessage;
            drawnow
            return;
        else
            app.PatternGroupDropDown.Value = -1;
            app.viewMode = 'node';

            app.CurrentOnlyCheckBox.Text = 'Current Node Only';

            %remove functionality not available in "node" view mode
            app.EditModeSwitch.Value = 'Pattern Values';
            app.enableUIPatternGroup(false);
            
        end
         p = cellfun(@(x) x.node == currentNode, app.Patterns);
%        here I need to add something like x.download to select only the
%        patterns that need to be downloaded to the node.
        app.setActivePatterns(p);
         % find the number of downloadable patterns
            downloadGroups = find(cellfun(@(x) x.download, app.nnps.groups));
            download = cellfun(@(x) ismember(x.group, downloadGroups), app.ActivePatterns);
            active2Download = size(app.ActivePatterns(download),1);
           
 %       n = size(app.ActivePatterns,1); %number of active patterns - must be all the patterns associated with that node
        
            % Added to display how many channel patterns are on current
            % node alread.  (48 allowed)
            nodeCurrentPattern(currentNode)=app.nnp.read(currentNode,'3300',49,'uint8');
            % list current number of patterns in node
            space=48-nodeCurrentPattern(currentNode);
            dmessage=sprintf('available pattern space in node %d\nActive Patterns to download %d',space,active2Download);
            app.ErrorsTextArea.Value = dmessage;
            drawnow              
        app.updateActivePattern(1);  %default to first row in table is active
        end

        % Value changed function: CommandEditField
        function CommandEditFieldValueChanged(app, event)
        x = app.CommandEditField.Value;
        app.PlotSlider.XData = [x x];
        app.PlotSliderSmall.XData = [x x];

        set(app.CommandEditField, 'FontAngle', 'normal', 'FontWeight', 'bold');
        app.updateStim();
        end

        % Cell edit callback: PatternTable
        function PatternTableCellEdit(app, event)
        indices = event.Indices;
        row = indices(1);
        col = indices(2);

        if col == 7 %Mute
            if event.NewData
                %Muted (dashed)
                app.Plots{row}.LineStyle = ':';
                app.SmallPlots{row}.LineStyle = ':';
            else
                %Unmuted (solid)
                app.Plots{row}.LineStyle = '-';
                app.SmallPlots{row}.LineStyle = '-';
            end
            app.updateStim();
        end


        end

        % Value changed function: AllMuteCheckBox
        function AllMuteCheckBoxValueChanged(app, event)
        mute = cell2mat(app.PatternTable.Data(:,7));
        if ~app.AllMuteCheckBox.Value %all(mute)
            % unmute all
            app.AllMuteCheckBox.Value = false;
            mute(:) = false;

            for row=1:length(app.Plots)
                %Unmuted (solid)
                app.Plots{row}.LineStyle = '-';
                app.SmallPlots{row}.LineStyle = '-';
            end
        else
            % mute all
            app.AllMuteCheckBox.Value = true;
            mute(:) = true;

            for row=1:length(app.Plots)
                %Muted (dashed)
                app.Plots{row}.LineStyle = ':';
                app.SmallPlots{row}.LineStyle = ':';
            end
        end

        app.PatternTable.Data(:,7) = mat2cell(mute, ones(length(mute),1));

        app.updateStim();
        end

        % Menu selected function: GroupsMenu
        function GroupsMenuSelected(app, event)

        h=app.groupEditUI;
        if ~isempty(h) && isprop(h, 'PatternGroupEditorFigure') && isvalid(h.PatternGroupEditorFigure)
            figure(h.PatternGroupEditorFigure);   %bring to front if already exists
        else
            app.groupEditUI = groupedit(app);
        end
        end

        % Button pushed function: AddChannelPatternButton
        function AddChannelPatternButtonPushed(app, event)

        i=length(app.Patterns)+1;

        group = app.PatternGroupDropDown.Value;

        %check if named group/cmdID exists for group number
        if group > length(app.nnps.groups) || isempty(app.nnps.groups{group})
            cmdID = [];
        else
            cmdID = app.nnps.groups{group}.cmdID;
        end

        if isempty(app.ActivePatterns) && isempty(cmdID) || isequal(cmdID, '?')  %JML note: this if case is no longer possible
            resp = inputdlg({sprintf('Create new channel pattern\n\nnode (1-15):'), 'channel (1-4):', 'cmdID (1-8):'},'Patterns');
            if length(resp)~=3
                return;
            end
            cmdID = str2double(resp{3});
            if length(cmdID)~=1 || isnan(cmdID) || cmdID<1 || cmdID>8
                warning('invalid cmdID entry')
                return;
            end
        else
            % Can I generate a list of Node/Chan/Muscles that are in the
            % current profile but not in the current pattern?
            % 1) check for viable profile designated
            % 2) Compare current patterns to profile 
            % 3) Generate string of n-ch-muscle pairts

            resp = inputdlg({sprintf('Create new channel pattern for group# %1.0f using cmdID %1.0f\n\nnode (1-15):', group, cmdID), 'channel (1-4):'}, 'Patterns');

            if length(resp)~=2
                figure(app.UIFigure);
                return;
            end

        end

        node = str2double(resp{1});
        ch = str2double(resp{2});

        if length(node)~=1 || isnan(node) || node<=0 || node>15 || ...
                length(ch)~=1  || isnan(ch)   || ch<1    || ch>4
            warning('invalid node/ch entry')
            return;
        end

        %             %If this is a new node, add it to the cell array of node
        %             %information
        %             if ~any(cellfun(@(x) x.node == node, app.Patterns))
        %
        %             end

        %shared parameters for new pattern can be copied from other
        %pattern in group if there already some
        if isempty(app.ActivePatterns)
            n = 2;
            cmd = [0 255];
        else
            if ~isequal(group, app.ActivePatterns{1}.group)
                warning('group does not match group dropdown')
                group = app.ActivePatterns{1}.group;

            end
            if ~isequal(cmdID, app.ActivePatterns{1}.cmdID)
                if ~isempty(cmdID)
                    warning('cmdID does not match group cmdID')
                end
                cmdID = app.ActivePatterns{1}.cmdID;
            end
            cmd = app.ActivePatterns{1}.cmd;

            n = app.ActivePatterns{1}.n;
        end

        p = cellfun(@(x) x.group == group && x.node == node && x.ch == ch, app.Patterns);
        if any(p)
            warning('node/ch combination already exists for this pattern group')
            return;
        end

        %force a column cell array
        if length(app.Patterns)==1 && i>1
            app.Patterns = [app.Patterns; {[]}];
        end

        app.Patterns{i}.group = group;
        app.Patterns{i}.node  = node;
        app.Patterns{i}.ch    = ch;
        app.Patterns{i}.cmdID = cmdID;
        app.Patterns{i}.n     = n;
        app.Patterns{i}.cmd   = cmd;
        app.Patterns{i}.pw    = zeros(1,n);
        app.Patterns{i}.amp   = ones(1,n)*100;


        app.Patterns = app.sortPatterns(app.Patterns);

        p = cellfun(@(x) x.group == group, app.Patterns);

        app.setActivePatterns(p)

        psel = find(cellfun(@(x) x.node == node && x.ch == ch, app.ActivePatterns), 1);
        app.updateActivePattern(psel);


        %Group may have gone from used to unused or vice versa or nodes may have been added
        app.updateDropDowns();
        end

        % Button pushed function: RemoveChannelPatternButton
        function RemoveChannelPatternButtonPushed(app, event)
        if isempty(app.ActivePatterns)
            return;
        end

        group = app.ActivePatterns{app.activePatternRow}.group;
        node = app.ActivePatterns{app.activePatternRow}.node;
        ch = app.ActivePatterns{app.activePatternRow}.ch;
% Add message box - DO YOU REALLY  WANT to REMOVE this channel?
        delMessage=sprintf('Are you sure you want to delete Node %d, Chan %d?',node,ch);
        delChoice=uiconfirm(app.UIFigure,delMessage,'Confirm Delete Channel','Icon','warning');
        switch delChoice
            case 'OK'
                psel = cellfun(@(x) x.group == group && x.node == node && x.ch == ch, app.Patterns);
        
                if sum(psel) == 1
                    app.Patterns(psel)=[];
        
                    p = cellfun(@(x) x.group == group, app.Patterns);
                    if any(p)
                        app.setActivePatterns(p)
                        app.updateActivePattern(1);
                    else
                        app.updateDropDowns(); %pattern is now unused
                        app.ColorTable.Data = [];
                        app.clearActivePatterns();
                    end
                elseif sum(psel) == 0
                    warning('could not locate pattern')
                else
                    warning('located multiple patterns')
                end
            case 'Cancel'
                figure(app.UIFigure);
                return
        end
        end

        % Value changed function: StimModeSwitch
        function StimModeSwitchValueChanged(app, event)

        if isequal( app.StimModeSwitch.Value, 'Displayed')
            app.HighBandwidthCheckBox.Visible = true;
        else
            app.HighBandwidthCheckBox.Visible = false;
        end
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
        if isempty(app.saveFile)
            app.SaveAsMenuSelected([])
        else
            patterns = app.Patterns;
            channels = app.nnps.channels;
            nodes = app.nnps.nodes;
            groups = app.nnps.groups;
            save(app.saveFile, 'patterns', 'channels', 'nodes','groups');
        end
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
         % I need to add a default path, but need to check if path is
            % valid first:
            if isfield(app.userFolders,'config')&& ~isempty(app.userFolders.config)
                fname=[app.userFolders.code,app.defaultSuffix];
                defname=fullfile(app.userFolders.config,fname); % name with path
            else
                defname=app.defaultName; % name without path
            end
            [filename, filepath] = uiputfile('.mat', 'Save Patterns File As...',defname);

            if filename
                app.saveFile = [filepath filename];
                app.SaveMenuSelected([]);
            end
        end

        % Menu selected function: ChannelsMenu
        function ChannelsMenuSelected(app, event)
        h=app.channelEditUI;
        if ~isempty(h) && isprop(h, 'ChannelsEditorFigure') && isvalid(h.ChannelsEditorFigure)
            figure(h.ChannelsEditorFigure);   %bring to front if already exists
        else
            app.channelEditUI = channeledit(app);
        end
        end

        % Button pushed function: StimONButton
        function StimONButtonPushed(app, event)
       figure(app.UIFigure);
        app.stimOn = true;
        app.CommandfromPMCheckBox.Enable = 'off';
%         app.PWAxes.BackgroundColor = [0 1 0];
%         app.PAAxes.BackgroundColor = [0 1 0];
        app.PWTab.BackgroundColor =  [0.8 .94 0.8];


        if isequal(app.StimModeSwitch.Value, 'Displayed')
            if app.CommandfromPMCheckBox.Value
                if app.HighBandwidthCheckBox.Value == true
                    resp = app.nnp.enterTestFeatures(1);
                else
                    resp = app.nnp.enterTestFeatures(0);
                end
                start(app.pmCommandTimer);
            else
                if app.HighBandwidthCheckBox.Value == true
%                     mode = 0: values are set in PG 3212.1-4 directly
%                     mode = 1: values are set in PM, PDOs 
%                     mode = 2: same as 1, only occur if changed

                    resp = app.nnp.enterTestStim(1);  % mode = 1: values are set in PM, PDOs 
                else
                    resp = app.nnp.enterTestStim(0); % mode = 0: values are set in PG 3212.1-4 directly
                end
            end
        else %Downloaded Patterns
            group = app.PatternGroupDropDown.Value;
            resp = app.nnp.nmt(0,'22');
            if ~isequal(resp, hex2dec('22'))
                app.ErrorsTextArea.Value = [['Could not confirm all groups off: ' app.nnp.lastError]; app.ErrorsTextArea.Value];
            end
            if app.CommandfromPMCheckBox.Value
                resp = app.nnp.enterPatient(group);
                start(app.pmCommandTimer);
            else
                resp = app.nnp.enterTestPatterns(group);
            end
        end

        if isequal(resp, true)
%             app.PWAxes.BackgroundColor = [0 1 0];
%             app.PAAxes.BackgroundColor = [0 1 0];

            app.PWTab.BackgroundColor = [0.8 .94 0.8];
            app.StimONButton.BackgroundColor = [0.0 1.0 0.0];
            app.StimOFFButton.BackgroundColor = [0.94 0.94 0.94];
        else
            app.ErrorsTextArea.Value = [['Stim On Unconfirmed: ' app.nnp.lastError]; app.ErrorsTextArea.Value];
%             app.PWAxes.BackgroundColor = [1 1 0]; %yellow
%             app.PAAxes.BackgroundColor = [1 1 0];
            app.PWTab.BackgroundColor = [1 1 0];

            app.StimONButton.BackgroundColor = [0.94 0.94 0.94];
            app.StimOFFButton.BackgroundColor = [0.94 0.94 0.94];
        end


        app.updateStim();

        %need to prevent other buttons while stim on, e.g, changing active patterns
        app.enableUIStim(false);
        drawnow; app.PWAxes.Visible = 'off'; app.PWAxes.Visible = 'on';

        end

        % Button pushed function: StimOFFButton
        function StimOFFButtonPushed(app, event)
        figure(app.UIFigure);
        app.stimOn = false;
        if isequal(app.pmCommandTimer.Running, 'on')
            stop(app.pmCommandTimer);
            pause(0.1);
        end
        app.CommandfromPMCheckBox.Enable = 'on';

        resp = app.nnp.enterWaiting();
        if isequal(resp, true)
%             app.PWAxes.BackgroundColor = [0.94 0.94 0.94];
%             app.PAAxes.BackgroundColor = [0.94 0.94 0.94];
            app.PWTab.BackgroundColor = [0.9412 0.9412 0.9412];

            app.StimOFFButton.BackgroundColor = [1.0 0.0 0.0];
            app.StimONButton.BackgroundColor = [0.94 0.94 0.94];

        else
            app.ErrorsTextArea.Value = [['Stim Off Unconfirmed: ' app.nnp.lastError]; app.ErrorsTextArea.Value];

            app.PWAxes.BackgroundColor = [1 1 0]; %yellow
            app.PAAxes.BackgroundColor = [1 1 0];

            app.StimONButton.BackgroundColor = [0.94 0.94 0.94];
            app.StimOFFButton.BackgroundColor = [0.94 0.94 0.94];
        end

        %             data = app.PatternTable.Data;
        %             if ~isempty(data)
        %                 data(:,8) = {'-'};
        %                 data(:,9) = {'-'};
        %                 app.PatternTable.Data = data;
        %             end
        app.updateStim(); %Update PW and Amp columns

        %need to reenable UI elements disabled by StimOn button
        app.enableUIStim(true);
        drawnow; app.PWAxes.Visible = 'off'; app.PWAxes.Visible = 'on';
        end

        % Button pushed function: WriteButton
        function WriteButtonPushed(app, event)

        if app.CurrentOnlyCheckBox.Value
            %Download only Active Patterns
%           p = app.ActivePatterns;
            downloadGroups = find(cellfun(@(x) x.download, app.nnps.groups));
            download = cellfun(@(x) ismember(x.group, downloadGroups), app.ActivePatterns);
            p = app.ActivePatterns(download);
        else
            %Download all enabled Patterns
            downloadGroups = find(cellfun(@(x) x.download, app.nnps.groups));
            download = cellfun(@(x) ismember(x.group, downloadGroups), app.Patterns);
            p = app.Patterns(download);
        end

        app.WriteButton.BackgroundColor = [0.7 0.7 0.7];
        drawnow;
        nPatterns = length(p);

        nodesReady = app.getActiveNodesForDownload();

        %Find number of patterns for each node
        % This assumes that the EEPROM has been erased, so the starting
        % pattern number in the EEPROOM will be 1
        % What if the EEPROM is not erased first?  Can we read the starting
        % number from 3300.31 and start writing from there?
        nodeCurrentPattern = zeros(max(nodesReady),1);
        nodeNumPatterns = zeros(max(nodesReady),1);

        for node = nodesReady'
            nodeNumPatterns(node) = sum(cellfun(@(x) x.node==node, p));

% here is where I could add the following to allow patterns to be added rather than starting at 0

            nodeCurrentPattern(node)=app.nnp.read(node,'3300',49,'uint8');
            % list current number of patterns in node
            dmessage=sprintf('current node patterns %d\n',nodeCurrentPattern(node));
            app.ErrorsTextArea.Value = dmessage;
            drawnow

            % the following would be used for the final comparison
            nodeNumPatterns(node)=nodeNumPatterns(node)+nodeCurrentPattern(node);
            % Here I actually only need to check the nodes that are
            % included in the pattern to be written (if not erasing)**********
            % got message for node 1 even though there should have been no
            % patterns to write to node 1
            if nodeNumPatterns(node)>48 % max allowed to write
                    warndlg(['Too many patterns for node ' num2str(node)' '!  Erase node and try again'], 'modal');
                    % clean up buttons
                    app.WriteButton.BackgroundColor = [0.94 0.94 0.94];
                    % add message to ErrorsTextArea
                    dmessage='Too many patterns - Erase node first';
                    app.ErrorsTextArea.Value = dmessage;
                    drawnow
                    return;
            end
%
        end

        for row=1:nPatterns

            node = p{row}.node;

            if ~ismember(node, nodesReady)
                continue;
            end

            group = p{row}.group;
            ch = p{row}.ch;
            n = p{row}.n;
            cmdID = p{row}.cmdID;

            try
                cmd = ones(1,20)*255;
                cmd(1:n) =p{row}.cmd;
                pw = ones(1,20)*255;
                pw(1:n) = p{row}.pw;
                amp = ones(1,20)*255;
                amp(1:n) = p{row}.amp;
            catch
                %textarea
                dmessage='Corrupted Pattern';
                app.ErrorsTextArea.Value = dmessage;
                disp('Corrupted Pattern')
                return
            end
 %textarea
 % for now, write to both so that I have a record in the matlab command
 % window to examine later.
            disp(['Downloading ' num2str(row) '/' num2str(length(p))  ' Group#' num2str(group) ', node#' num2str(node), 'ch#' num2str(ch)])
            dmessage=['Downloading ' num2str(row) '/' num2str(length(p))  ' Group#' num2str(group) ', node#' num2str(node), 'ch#' num2str(ch)];
            app.ErrorsTextArea.Value = dmessage;
            step = 1;
            retries = 0;
            while retries < 3
                switch step
                    case 1
                        resp = app.nnp.write(node, '3301', 1, ch , 'uint8');
                        if isequal(resp, 0)
                            step = step + 1;
                            retries = 0;
                        else
                            errStr = 'ch unconfirmed';
                            retries = retries + 1;
                        end
                    case 2
                        resp = app.nnp.write(node, '3301', 2, cmdID,'uint8');
                        if isequal(resp, 0)
                            step = step + 1;
                            retries = 0;
                        else
                            errStr = 'cmdID unconfirmed';
                            retries = retries + 1;
                        end
                    case 3
                        resp = app.nnp.write(node, '3301', 3, n, 'uint8');
                        if isequal(resp, 0)
                            step = step + 1;
                            retries = 0;
                        else
                            errStr = 'n unconfirmed';
                            retries = retries + 1;
                        end
                    case 4
                        resp = app.nnp.write(node, '3301', 4, cmd, 'uint8');
                        if isequal(resp, 0)
                            step = step + 1;
                            retries = 0;
                        else
                            errStr = 'cmd unconfirmed';
                            retries = retries + 1;
                        end
                    case 5
                        resp = app.nnp.write(node, '3301', 5, pw, 'uint8');
                        if isequal(resp, 0)
                            step = step + 1;
                            retries = 0;
                        else
                            errStr = 'pw unconfirmed';
                            retries = retries + 1;
                        end
                    case 6
                        resp = app.nnp.write(node, '3301', 6, amp, 'uint8');
                        if isequal(resp, 0)
                            step = step + 1;
                            retries = 0;
                        else
                            errStr = 'amp unconfirmed';
                            retries = retries + 1;
                        end
                    case 7
                        resp = app.nnp.nmt(node, 'A5', nodeCurrentPattern(node)+1);  %Load Pattern to EEPROM
                        if isequal(resp, hex2dec('A5'))
                            pause(0.5);  %PG needs time to push data into EEPROM
                            step = step + 1;
                            retries = 0;
                            nodeCurrentPattern(node) = nodeCurrentPattern(node)+1; % increment where pattern is to be written
                        else
                            errStr = 'cmd unconfirmed';
                            retries = retries + 1;
                        end
                    case 8
                        resp = app.nnp.write(node, '3300', nodeCurrentPattern(node), group, 'uint8');
                        if isequal(resp, 0)
                            if nodeCurrentPattern(node) == nodeNumPatterns(node)
                                step = step + 1;
                                retries = 0;
                            else
                                errStr = [];
                                break;  %done with pattern
                            end
                        else
                            errStr = 'set group unconfirmed';
                            retries = retries + 1;
                        end
                    case 9
                        resp = app.nnp.write(node, '3300', 49, nodeNumPatterns(node), 'uint8');
                        if isequal(resp, 0)
                            errStr = [];
                            app.nnp.nmt(node,'0A'); % save the restore parameters
                            break;  %done with pattern
                        else
                            errStr = 'set nPatterns unconfirmed';
                            retries = retries + 1;
                        end
                end % end switch
            end % end retry while loop
            if ~isempty(errStr)
                app.ErrorsTextArea.Value=[app.ErrorsTextArea.Value;'Finished writing patterns'];
                %app.ErrorsTextArea.Value = [[ errStr ': ' app.nnp.lastError]; app.ErrorsTextArea.Value];
                app.WriteButton.BackgroundColor = [1 0 0];
                pause(0.5);
                app.WriteButton.BackgroundColor = [0.94 0.94 0.94];
                return;
            end

        end %end node for loop
        app.WriteButton.BackgroundColor = [0 1 0];
        pause(0.5);
        app.WriteButton.BackgroundColor = [0.94 0.94 0.94];
        end

        % Button pushed function: ReadButton
        function ReadButtonPushed(app, event)

        nodeModes = app.nnp.getStatus();
        nodesReady = find(cellfun(@(x) isequal(x, 'Waiting'), nodeModes));

        app.ReadPatterns = {};
        groupOnly = [];

        if app.CurrentOnlyCheckBox.Value
            if isequal(app.viewMode, 'patterngroup')
                groupOnly = app.PatternGroupDropDown.Value;
            else
                if ismember(app.NodeDropDown.Value, nodesReady)
                    nodesReady = app.NodeDropDown.Value;
                else
                    warning('node not ready')
                    return; %TODO warning?
                end
            end
        end

        app.ReadButton.BackgroundColor = [0.7 0.7 0.7];
        drawnow;
        for node = nodesReady'
            if node == 7 %TODO: nodes should be checked against system Nodes from file
                continue
            end

            resp = double(app.nnp.read(node, '3300', 49)); %Read Number of Patterns
            if length(resp) == 1
                nodeNumPatterns = resp;
            else
                app.ErrorsTextArea.Value = [['read nPatterns failed: ' app.nnp.lastError]; app.ErrorsTextArea.Value];
                return;
            end


            for i = 1:nodeNumPatterns
                fprintf('Reading %1.0f/%1.0f for node#%1.0f\n', i, nodeNumPatterns, node);
                retries = 0;
                step = 1;
                errStr = [];
                skip = false;

                while retries < 3
                    switch step
                        case 1
                            resp = double(app.nnp.read(node, '3300', i)); %Read Group
                            if length(resp) == 1
                                group = resp;
                                if isempty(groupOnly) || isequal(groupOnly, group)
                                    step = step + 1;
                                    retries = 0;
                                elseif ~isempty(groupOnly)
                                    fprintf('...skipping (not selected group)\n');
                                    skip = true;
                                    break; %break from retry while loop, no errStr
                                end
                            else
                                retries = retries + 1;
                                errStr = 'read group failed';
                            end
                        case 2
                            resp = app.nnp.nmt(node, 'A6', i); %Read Pattern from EEPROM
                            if isequal(resp, hex2dec('A6'))
                                %JML:Need pause here?
                                step = step + 1;
                                retries = 0;
                            else
                                retries = retries + 1;
                                errStr = 'read EEPROM failed';
                            end
                        case 3
                            resp = double(app.nnp.read(node, '3301', 1, 'uint8'));
                            if length(resp) == 1
                                ch = resp;
                                step = step + 1;
                                retries = 0;
                            else
                                retries = retries + 1;
                                errStr = 'read ch failed';
                            end
                        case 4
                            resp = double(app.nnp.read(node, '3301', 2, 'uint8'));
                            if length(resp) == 1
                                cmdID = resp;
                                step = step + 1;
                                retries = 0;
                            else
                                retries = retries + 1;
                                errStr = 'read cmdID failed';
                            end
                        case 5
                            resp = double(app.nnp.read(node, '3301', 3, 'uint8'));
                            if length(resp) == 1
                                n = resp;
                                step = step + 1;
                                retries = 0;
                            else
                                retries = retries + 1;
                                errStr = 'read cmdID failed';
                            end
                        case 6
                            resp = double(app.nnp.read(node, '3301', 4, 'uint8'));
                            if length(resp) == 20
                                cmd = resp(1:n);
                                step = step + 1;
                                retries = 0;
                            else
                                retries = retries + 1;
                                errStr = 'read cmd failed';
                            end
                        case 7
                            resp = double(app.nnp.read(node, '3301', 5, 'uint8'));
                            if length(resp) == 20
                                pw = resp(1:n);
                                step = step + 1;
                                retries = 0;
                            else
                                retries = retries + 1;
                                errStr = 'read pw failed';
                            end
                        case 8
                            resp = double(app.nnp.read(node, '3301', 6, 'uint8'));
                            if length(resp) == 20
                                amp = resp(1:n);
                                break; %done with pattern
                            else
                                retries = retries + 1;
                                errStr = 'read pw failed';
                            end
                    end %end switch
                end %end retry while loop


                if ~isempty(errStr)
                    app.ErrorsTextArea.Value = [[ errStr ': ' app.nnp.lastError]; app.ErrorsTextArea.Value];
                    app.ReadButton.BackgroundColor = [1 0 0];
                    pause(0.5);
                    app.ReadButton.BackgroundColor = [0.94 0.94 0.94];
                    return;
                elseif ~skip
                    app.ReadPatterns = [app.ReadPatterns; struct('group', group, 'node', node, 'ch', ch, 'cmdID', cmdID, 'n', n, 'cmd', cmd, 'pw', pw, 'amp', amp)];
                end
            end
        end % end node for loop
        app.ReadPatterns = app.sortPatterns(app.ReadPatterns);

        app.ReadButton.BackgroundColor = [0 1 0];
        pause(0.5);
        app.ReadButton.BackgroundColor = [0.94 0.94 0.94];

        groupsReadPatterns = unique(cellfun(@(x) x.group, app.ReadPatterns));
        fprintf('Checking read pattern groups for valid breakpoints')
        for group = groupsReadPatterns'
            iGroup = cellfun(@(x) x.group==group, app.ReadPatterns); % find app.ReadPatterns belonging to group 1
            fprintf('Group %2.0f\n', group);
            breakpoints = cell2mat(cellfun(@(x) x.cmd, app.ReadPatterns(iGroup), 'UniformOutput', false ));

            if any(breakpoints(:,1)~=0)
                breakpoints(:,1)=0;
                disp('Invalid first breakpoint fixed')
            end

            if any(breakpoints(:,end)~=255)
                breakpoints(:,end)=255;
                disp('Invalid last breakpoint fixed')
            end

            choices = unique(breakpoints, 'rows');
            n = size(choices,1);

            if n>1
                choicesStr = cell(n,1);
                for i=1:n
                    choicesStr{i} = num2str(choices(i,:));
                end

                disp(breakpoints)

                resp = listdlg('PromptString', {'Different breakpoints used between channel app.ReadPatterns in same group.  Select the set of breakpoints you want to use'}, ...
                    'ListString', choicesStr, 'SelectionMode', 'single');
                if length(resp)==1
                    for p=find(iGroup)'
                        app.ReadPatterns{p}.cmd = choices(resp,:);
                    end
                end
            else
                % disp('All match')
            end
        end
        end

        % Button pushed function: CompareButton
        function CompareButtonPushed(app, event)
        if isempty(app.ReadPatterns)
            warndlg('do read first', 'Compare Patterns','modal')
        end

        if app.CurrentOnlyCheckBox.Value
%            p = app.ActivePatterns;
            downloadGroups = find(cellfun(@(x) x.download, app.nnps.groups));
            download = cellfun(@(x) ismember(x.group, downloadGroups), app.ActivePatterns);
            p = app.ActivePatterns(download);
        else
            %compare only to downloadable patterns
            groups = find(cellfun(@(x) ~isempty(x) && x.download, app.nnps.groups)); %downloadable groups
            d = cellfun(@(x) ismember(x.group, groups), app.Patterns); %downloadable patterns
            p =  app.Patterns(d);
        end

        if isequal(app.ReadPatterns, p)
            disp('Patterns identical')
        else
            if length(app.ReadPatterns) ~= length(p)
                disp(['Patterns read from Modules: ' num2str(length(app.ReadPatterns)) ' , Patterns in App:' num2str(length(p))]);
            else
                disp('Same number of patterns on modules and in file, but differences present...')
                differences = [];
                for i=1:length(app.Patterns)
                    if ~isequal(app.ReadPatterns{i}, app.Patterns{i})
                        differences = [differences;i];
                    end
                end

                for i=1:length(differences)
                    fprintf('\n---------------------------------\n');
                    fprintf('Pattern #%2.0f in File\n', i)
                    disp(app.Patterns{differences(i)})
                    fprintf('Pattern #%2.0f in Module\n', i)
                    disp(app.ReadPatterns{differences(i)})
                    fprintf('\n---------------------------------\n');
                end
            end
        end

        end

        % Cell selection callback: PatternTable
        function PatternTableCellSelection(app, event)
        row = event.Indices(1);
        app.updateActivePattern(row);
        end

        % Cell edit callback: PatternValuesTable
        function PatternValuesTableCellEdit(app, event)
        %disp('PatternValueChange');
        row = event.Indices(1);
        col = event.Indices(2);
        newData = event.NewData;
        if isempty(newData) || ~isnumeric(newData)
            app.PatternValuesTable.Data(row, col) = event.PreviousData;
            return
        else
            newData = floor(newData);
            app.PatternValuesTable.Data(row, col) = newData;
        end
        switch (col)
            case 1 %Breakpoint (changes all patterns)
                nrows = size(app.PatternValuesTable.Data, 1);
                breakpoints = app.PatternValuesTable.Data(:,1);

                % cannot change first or last breakpoint, or change a breakpoint outside range of neighboring breakpoints
                if isequal(app.viewMode, 'patterngroup') && row>1 && row<nrows && newData < breakpoints(row+1) && newData > breakpoints(row-1)
                    for i=1:length(app.Plots)
                        app.Plots{i}.XData(row) = newData;
                        app.SmallPlots{i}.XData(row) = newData;
                    end
                    app.PlotBars{row-1}.XData = [newData newData];
                    app.updatePatternBreakpoints()
                else
                    app.PatternValuesTable.Data(row, col) = event.PreviousData;
                end

            case 2 %PW
                if newData <= 255 && newData >= 0
                    app.Plots{app.activePatternRow}.YData(row) = newData; %assumes large plot is PW
                    app.updateFromPatternValuesTable();
                else
                    app.PatternValuesTable.Data(row, col) = event.PreviousData;
                end
            case  3 %Amp
                if newData <= 200 && newData >= 0
                    app.SmallPlots{app.activePatternRow}.YData(row) = newData; %assumes small plot is Amp
                    app.updateFromPatternValuesTable();
                else
                    app.PatternValuesTable.Data(row, col) = event.PreviousData;
                end
        end


        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if app.OverrideNumlockCheckBox.Value
                key= app.overrideNumLock(key);
            end
            switch key
                case 'alt'
                    app.altPressed = true;
                case 'shift'
                    app.shiftPressed = true;
                case 'control'
                    app.ctrlPressed = true;

                case 'insert'
                    app.insertPressed = true;
                case {'semicolon'}

                    if app.CommandfromPMCheckBox.Value
                        return
                    end
                    if app.altPressed
                        step = 10;
                    else
                        step = 1;
                    end
                    xc = max(app.CommandEditField.Value - step, 0);
                    app.PlotSlider.XData = [xc xc];
                    app.PlotSliderSmall.XData = [xc xc];
                    app.CommandEditField.Value = xc;
                    set(app.CommandEditField, 'FontAngle', 'italic', 'FontWeight', 'normal')
                    drawnow limitrate
                    %trigger updateStim in KeyRelease

                case 'quote'

                    if app.CommandfromPMCheckBox.Value
                        return
                    end
                    if app.altPressed
                        step = 10;
                    else
                        step = 1;
                    end
                    xc = min(app.CommandEditField.Value + step, 255);
                    app.PlotSlider.XData = [xc xc];
                    app.PlotSliderSmall.XData = [xc xc];
                    app.CommandEditField.Value = xc;
                    set(app.CommandEditField, 'FontAngle', 'italic', 'FontWeight', 'normal');
                    drawnow limitrate
                    %trigger updateStim in KeyRelease

                    % from testKeyPress
                case {'uparrow', 'downarrow'} %up or down arrow
                    app.lockoutSec=0.10; % added here to change without restarting
                    %                     app.ErrorsTextArea.Value = [sprintf('p%d',int8(app.checkLockout)); app.ErrorsTextArea.Value];
                    if app.checkLockout   % after key release, do not need to check lockout
                        app.newTime=toc(app.lastUpdate);
                        if app.newTime>app.lockoutSec % ignore if not long enough
                            %                            nt=sprintf('%5.2f',app.newTime);
                            %                             app.ErrorsTextArea.Value = [nt; app.ErrorsTextArea.Value];
                            app.lastUpdate=tic;
                            app.stepSize=app.bigStep; % held so us big step
                            app.processUpDownKey(key);
                            app.checkLockout=true;
                            %                            app.ErrorsTextArea.Value = ['clT1'; app.ErrorsTextArea.Value];
                            app.keyRelUpdate=false;
                        end
                        %                     end
                    else % not checkLockout

                        app.checkLockout=true;

                        % app.ErrorsTextArea.Value = ['clT2'; app.ErrorsTextArea.Value];
                        if app.keyRelUpdate % should always be false at this point
                            % app.ErrorsTextArea.Value = ['KR'; app.ErrorsTextArea.Value];
                            app.keyRelUpdate=false; % more than one KP
                            % Since this is first duplicate, send out change
                            app.lastUpdate=tic;
                            app.stepSize=app.bigStep; % held so us big step
                            app.processUpDownKey(key);

                        else % not keyRelease and not checkLockout
                            app.keyRelUpdate=true; % first time through
                        end
                    end
            end
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
        key = event.Key;
        if app.OverrideNumlockCheckBox.Value
            key= app.overrideNumLock(key);
        end
        switch key
            case 'leftarrow'
                
                %jump slider to previous breakpoint
                if app.CommandfromPMCheckBox.Value
                    return
                end
                cmd = app.CommandEditField.Value;
                i = find(app.Plots{1}.XData<cmd, 1, 'last');
        
                if ~isempty(i)
                    x = app.Plots{1}.XData(i);
                    app.PlotSlider.XData = [x x];
                    app.PlotSliderSmall.XData = [x x];
                    app.CommandEditField.Value = x;
                    %                         end
                    app.updateStim();
                end
            case 'rightarrow'
                
                %jump slider to next breakpoint
                if app.CommandfromPMCheckBox.Value
                    return
                end
                cmd = app.CommandEditField.Value;
                i = find(app.Plots{1}.XData>cmd, 1, 'first');
        
                if ~isempty(i)
                    x = app.Plots{1}.XData(i);
                    app.PlotSlider.XData = [x x];
                    app.PlotSliderSmall.XData = [x x];
                    app.CommandEditField.Value = x;
                    %                         end
                    app.updateStim();
                end
        
            case 'alt'
                app.altPressed = false;
        
            case 'shift'
                app.shiftPressed = false;
        
            case 'control'
                app.ctrlPressed = false;
        
            case {'backslash','multiply'}
                %                     app.clearPressed = false;
                app.MuteToggleCurrentRow();
            case 'home'
                % Max PW in profile

            case 'end'
                % Threshold PW in profile

            case 'rightbracket'
                % Unmute all
                app.AllMuteCheckBox.Value = false;
                app.AllMuteCheckBoxValueChanged([]);
        
            case 'leftbracket'
                % Mute all
                app.AllMuteCheckBox.Value = true;
                app.AllMuteCheckBoxValueChanged([]);
        
            case 'insert'
                app.insertPressed = false;
        
            case 'divide'
                if app.insertPressed
                   % Mute all
                    app.AllMuteCheckBox.Value = true;
                    app.AllMuteCheckBoxValueChanged([]); 
                else
                     % Unmute all
                    app.AllMuteCheckBox.Value = false;
                    app.AllMuteCheckBoxValueChanged([]);
                end
            case {'quote', 'semicolon'}
                if app.CommandfromPMCheckBox.Value
                    return
                end
                app.CommandEditFieldValueChanged(event);
        
                % from testKeyPress:
            case {'comma','pagedown'} % may need to see how the rows advance (up or down)
                %change to next channel pattern
                if app.activePatternRow < length(app.ActivePatterns)
                    app.updateActivePattern(app.activePatternRow+1);
                else
                    app.updateActivePattern(1);
                end
            case {'period','pageup'}
                %change to previous channel pattern
                if app.activePatternRow >1
                    app.updateActivePattern(app.activePatternRow-1);
                else
                    app.updateActivePattern(length(app.ActivePatterns));
                end
            case {'uparrow', 'downarrow'} %up or down arrow
                
                if app.keyRelUpdate
                    app.lastUpdate=tic;
                    app.stepSize=app.littleStep;
                    app.processUpDownKey(key);
                end
                
            case {'hyphen','delete'}
                app.StimOFFButtonPushed(event);
        
            case {'subtract','equal','clear'} % stim toggle ***added clear
                if app.stimOn
                    app.StimOFFButtonPushed(event);
                else
                app.StimONButtonPushed(event);
                end
                
        
            case 'space'
                if app.ctrlPressed && app.stimOn == false
                    app.StimONButtonPushed(event);
                else
                    app.StimOFFButtonPushed(event);
                end
        
        end
        %for key repeat logic
        app.checkLockout=false;
        app.keyRelUpdate=false;
        %             app.ErrorsTextArea.Value = ['release'; app.ErrorsTextArea.Value];
        %             app.ErrorsTextArea.Value = [sprintf('x%d',int8(app.checkLockout)); app.ErrorsTextArea.Value];

        end

        % Button pushed function: EraseButton
        function EraseButtonPushed(app, event)


        resp = questdlg('Are you sure you want to Erase Patterns space in PG4 EEPROM.  This takes 30s per node and is not required before loading new patterns?');
        if ~isequal(resp, 'Yes')
            return;
        end

        nodes = app.getActiveNodesForDownload()';
        lastNode=0;
        for node = nodes
            if node == 7
                continue
            end

            resp = app.nnp.write(node, '3300', 49, 0); %no active patterns
            if isequal(resp, 0)
                % Here I also need to set all the other elements of 3300 to
                % 0 as well 
                % to do that would take 48 additional writes because you
                % cannot use block write to remote modules.
                %resp = app.nnp.write(node, '3300', i, 0); where i=1:48
                for i=1:48
                    resp=app.nnp.write(node,'3300',i,uint8(0));
                    if ~isequal(resp,0)
                        fprintf('error 3300 row %d/n',i);
                    end
                end
                app.nnp.nmt(node,'0A'); % save the restore parameters in case no patterns written back later
                app.nnp.nmt(node, '90', 2); %delete pattern space
            else
                app.ErrorsTextArea.Value = [['Could not set pattern count to 0: ' app.nnp.lastError]; app.ErrorsTextArea.Value];
            end
            % put countdown timer here
            if node~=lastNode
                lastNode=node;
                tErase=tic;
                tCount=0;
                lastDisp=0;
                app.ErrorsTextArea.Value = ['Erasing node ' num2str(node)];
                disp(['Erasing node ' num2str(node)]);
                drawnow
            end
            while tCount<30
                tCount=toc(tErase);
                if tCount-lastDisp>3 % update every N seconds
                    dmessage=['Erasing... ' num2str(round(tCount)) ' sec'];
                    app.ErrorsTextArea.Value = dmessage;
                    drawnow
                    disp(['Erasing... ' num2str(round(tCount)) ' sec']);                    
                    lastDisp=round(tCount);
                end
            end
            disp(['Finished erasing node ' num2str(node)]);
             app.ErrorsTextArea.Value = ['Finished erasing node ' num2str(node)];
        end
        end

        % Menu selected function: NodesMenu
        function NodesMenuSelected(app, event)
        h=app.nodeEditUI;
        if ~isempty(h) && isprop(h, 'NodeEditorFigure') && isvalid(h.NodeEditorFigure)
            figure(h.NodeEditorFigure);   %bring to front if already exists
        else
            app.nodeEditUI = nodeedit(app);
        end
        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
        if  ~isempty(app.Patterns) || ~isempty(app.nnps) && (~isempty(app.nnps.channels) || ~isempty(app.nnps.nodes) || ~isempty(app.nnps.groups))
            resp = questdlg('You may have unsaved changes.  Do you want to continue with a new file?');
            if ~isequal(resp, 'Yes')
                return;
            end
        end

        app.nnps.channels = {};
        app.nnps.groups = {};
        app.nnps.nodes = {};

        app.Patterns = {};
        app.ActivePatterns = {};
        app.activePatternRow = [];
        app.saveFile=[];


        app.stimOn = false;

        app.clearActivePatterns();
        drawnow;

        app.updateDropDowns();


        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
        disp('Version Info')
        end

        % Menu selected function: HelpSubMenu
        function HelpSubMenuSelected(app, event)
        msgbox('Editing X-axis (breakpoints) only available in breakpoint edit mode while in Pattern Group view mode','Help', 'help')
        end

        % Value changed function: DownloadableCheckBox
        function DownloadableCheckBoxValueChanged(app, event)
        %change the setting if the groupedit window is closed,
        %otherwise push groupedit window to focus
        h=app.groupEditUI;
        if ~isempty(h) && isprop(h, 'PatternGroupEditorFigure') && isvalid(h.PatternGroupEditorFigure)
            figure(h.PatternGroupEditorFigure);   %bring to front if already exists
            app.DownloadableCheckBox.Value = ~app.DownloadableCheckBox.Value;
        else
            app.nnps.groups{app.PatternGroupDropDown.Value}.download = app.DownloadableCheckBox.Value;
        end
        end

        % Button pushed function: LoadReadPatternsintoEditorButton
        function LoadReadPatternsintoEditorButtonPushed(app, event)
        if isempty(app.ReadPatterns)
            warndlg('Do read first','Patterns', 'modal')
        else
            resp = questdlg('Do you want to save changes to the existing file before loading the read patterns?','Loading Read Patterns');
            if isequal(resp, 'Yes') || isequal(resp, 'No')
                if isequal(resp, 'Yes')
                    app.SaveMenuSelected([]);
                end
                resp = questdlg('Do you want to remove existing system data (groups/channels/nodes)?','Loading Read Patterns');
                if isequal(resp, 'Yes')
                    app.nnps.channels ={};
                    app.nnps.groups ={};
                    app.nnps.nodes ={};
                end
                app.Patterns = app.ReadPatterns;
                app.activePatternRow = [];
                app.saveFile=[];

                app.PatternGroupDropDown.Value = -1;
                app.NodeDropDown.Value = -1;

                app.stimOn = false;

                app.clearActivePatterns();
                drawnow;

                app.updateDropDowns();
            end
        end
        end

        % Button pushed function: SetAllPWButton
        function SetAllPWButtonPushed(app, event)
        userResp = inputdlg({sprintf('Set all pulsewidths in channel pattern  (use either constant or multiply factor) \nconstant (0-255):');'multiply factor (1 means no change)'}, 'Set Pulse Widths');
        resp = str2double(userResp);
        if isempty(resp) || (isnan(resp(1)) && isempty(resp(2)))
            return
        else
            pw = app.PatternValuesTable.Data(:,2);
            if ~isnan(resp(2)) %if factor supplied, use it
                pw = round(max(min(resp(2)*pw, 255),0));
            elseif ~isnan(resp(1)) %otherwise use constant
                if resp(1)>=0 && resp(1)<255
                    pw(:) = round(resp(1));
                end
            end
            app.PatternValuesTable.Data(:,2) = pw;
            app.Plots{app.activePatternRow}.YData = pw';
            app.updateFromPatternValuesTable();
        end

        end

        % Button pushed function: SetAllAmpButton
        function SetAllAmpButtonPushed(app, event)
        userResp = inputdlg({sprintf('Set all amplitudes in channel pattern (use either constant or multiply factor) \nconstant (0-200):');'multiply factor (1 means no change)'}, 'Set Pulse Amplitudes');
        resp = str2double(userResp);
        if isempty(resp) || (isnan(resp(1)) && isempty(resp(2)))
            return
        else
            amp = app.PatternValuesTable.Data(:,3);
            if ~isnan(resp(2)) %if factor supplied, use it
                amp = round(max(min(resp(2)*amp, 200), 0));
            elseif ~isnan(resp(1)) %otherwise use constant
                if resp(1)>=0 && resp(1)<200
                    amp(:) = round(resp(1));
                end
            end
            app.PatternValuesTable.Data(:,3) = amp;
            app.SmallPlots{app.activePatternRow}.YData = amp';
            app.updateFromPatternValuesTable();
        end
        end

        % Cell selection callback: ColorTable
        function ColorTableCellSelection(app, event)
        row = event.Indices(1);
        app.updateActivePattern(row);
        end

        % Menu selected function: LoadfromMATfileMenu
        function LoadfromMATfileMenuSelected(app, event)
        if  ~isempty(app.Patterns) || ~isempty(app.nnps) && (~isempty(app.nnps.channels) || ~isempty(app.nnps.nodes) || ~isempty(app.nnps.groups))
            resp = questdlg('You may have unsaved changes.  Do you want to continue loading channel, group, node file?');
            if ~isequal(resp, 'Yes')
                return;
            end
        end

        [filename, filepath] = uigetfile('.mat', 'Select NNP System File');
        if filename
            ld = load([filepath filename]);
            TempFile = [filepath filename];  % save filename as temporary until the file is checked

        else
            return;
        end
        fields = {'channels', 'nodes', 'groups'};
        iMissing = find(~isfield(ld, fields));

        str = [];
        for i=1:length(iMissing)
            str = [str ', ' fields{iMissing(i)}];
        end
        if ~isempty(str)
            warndlg(['Invalid NNP System File.  Does not contain variable(s): ' str '.'], 'Open File')
            return;
        end
        %               If the file is invalid, we don't want to update anything, including the file name
        %               app.saveFile = TempFile;
        app.nnps.channels = ld.channels;
        app.nnps.nodes = ld.nodes;
        app.nnps.groups = ld.groups;
        app.updateDropDowns();
        figure(app.UIFigure); %return focus after nnp opened if needed  
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
        if ishandle(app.callingFigure) % check that is is valid
            figure(app.callingFigure); % bring to front
        end
        delete(app)

        end

        % Button pushed function: clearButton
        function clearButtonPushed(app, event)
        app.ErrorsTextArea.Value='';
        end

        % Value changed function: CommandfromPMCheckBox
        function CommandfromPMCheckBoxValueChanged(app, event)
        if app.CommandfromPMCheckBox.Value
            app.SubindexEditField.Enable = 'on';
            app.Scpt11Button.Enable = 'on';
        else
            app.SubindexEditField.Enable = 'off';
            app.Scpt11Button.Enable = 'off';
        end

        end

        % Menu selected function: LoadCNGPfromNNPSFileMenu
        function LoadCNGPfromNNPSFileMenuSelected(app, event)
            promptstr={'Import the following:',...
                'CTRL click for multiple',''};
            list={'Nodes','Channels','Groups','Patterns'};
            listboxsize=[160,100];
            initvalues=[1,2];
            boxName='Load from nnps file';
            okString='Import';
            [indx,tf]=listdlg('PromptString',promptstr,'ListString',list,...
                'ListSize',listboxsize,'InitialValue',initvalues,...
                'Name',boxName,'OKString',okString);
            if tf
                [success, newchannels, newnodes, newgroups, newpatterns]=readnnpsCNGP();
                if success==1
                    if any(indx==1)
                        app.nnps.nodes=newnodes;
                    end
                    if any(indx==2)
                        app.nnps.channels=newchannels;
                    end
                    if any(indx==3)
                        app.nnps.groups=newgroups;
                    end
                    if any(indx==4)
                        app.Patterns=newpatterns;
                    end
                    app.updateDropDowns();
                    figure(app.UIFigure); %return focus after nnp opened if needed  
                end
            end
        end

        % Menu selected function: LoadProfile
        function LoadProfileMenuSelected(app, event)
         defName=fullfile(app.defaultPath,app.defaultProfileName);
            [filename, filepath] = uigetfile('.mat','Select Profile file',defName);
            if filename
                app.profileName=fullfile(filepath, filename);
%                 app.readProfile();
% The profile is used to create patterns and the name is passed through
% In order to display the patterns I need the nodes and channels to be
% loaded.  I can read the stim module numbers in from the profile, but not
% the BP modules.   I can read the channel names associated with each
% channel in the profile.  For now, it may be easiest to simply enter in
% the node and channel info manually, because I don't have all the node
% information in the profile.   I didn't think to include node names or
% min/man in system setup for system diagram.  
% The easier solution is to ask here "Do you want to import channel names
% from profile.  If so, import load the names from columns 2,3,4 of
% profile. 

if exist(app.profileName,'file')==2
    resp = questdlg('Do you want to load channel names from the profile?');
    if ~isequal(resp, 'Yes')
        return;
    end

    ld=load(app.profileName);
    if isfield(ld,'data')
        % assume structure nnps exists
        data=ld.data; %make it easier to write
        nrows=size(data,1);
        for i=1:nrows
            % i=2;
            node=data{i,2};
            chan=data{i,3};
            app.nnps.channels{node,chan}.name=char(data{i,4});
            app.nnps.channels{node,chan}.min=0;
            app.nnps.channels{node,chan}.max=255;
            app.nnps.channels{node,chan}.notes=data{i,10};
        end
    end
end


            end
             figure(app.UIFigure); %return focus after nnp opened if needed      
        end

        % Menu selected function: SaveActiveFGMenu
        function SaveActiveFGMenuSelected(app, event)
      % The purpose of this test is to save the currently active functional
      % group, then open the file to make sure I have enough information
      % saved to properly plot the pattern data I need.
      % If this works, I will make these values the input to the plotting
      % app
            [filename, filepath] = uiputfile('.mat', 'Save Active FG As...');
        if filename
            saveFile = [filepath filename];
            ActivePatterns = app.ActivePatterns;
            channels = app.nnps.channels;
            nodes = app.nnps.nodes;
            groups = app.nnps.groups;
            currentGroup = app.PatternGroupDropDown.Value;
            save(saveFile, 'ActivePatterns', 'channels', 'nodes','groups','currentGroup');
        end
         figure(app.UIFigure); %return focus after nnp opened if needed      
        end

        % Menu selected function: ExportActiveFGMenu
        function ExportActiveFGMenuSelected(app, event)
            fG.ActivePatterns = app.ActivePatterns;
            fG.channels = app.nnps.channels;
            fG.nodes = app.nnps.nodes;
            fG.groups = app.nnps.groups;
            fG.currentGroup = app.PatternGroupDropDown.Value;
            patternDisplay(app.UIFigure,fG,0); %open separate windows to print
        end

        % Menu selected function: ExportAllFGtoPNGXLSMenu
        function ExportAllFGtoPNGXLSMenuSelected(app, event)
            % Step through all the patterns listed and export/print each
            % one of them.
            % app.nnps.groups contains cell array of all groups between 1
            % and highest number group.  But some are empty sets.  So we
            % need a list of all available (non-empty) groups.
            % NEED TO ADD - directory to save all these into
            printDir=uigetdir;
            iData=app.PatternGroupDropDown.ItemsData;
            iDataLength=length(iData);
            % These don't change:
                fG.channels = app.nnps.channels;
                fG.nodes = app.nnps.nodes;
                fG.groups = app.nnps.groups;
            % These will change:    
            for i=3:iDataLength
                app.PatternGroupDropDown.Value=iData(i);
                app.PatternGroupDropDownValueChanged();
                fG.ActivePatterns = app.ActivePatterns;
                fG.currentGroup = app.PatternGroupDropDown.Value;
                patternDisplay(app.UIFigure,fG,1,printDir); %open separate windows to print
            end
        end

        % Menu selected function: CommunicationsIssuesMenu
        function CommunicationsIssuesMenuSelected(app, event)
             commchecklist();
        end

        % Menu selected function: ScriptsDisableMenu
        function ScriptsDisableMenuSelected(app, event)
            app.nnp.enterWaiting();
            response=app.nnp.nmt(7, '8F'); %disable scripts
            if response==hex2dec('8F')
                 app.ErrorsTextArea.Value = 'Scripts Disabled';
            else
                app.ErrorsTextArea.Value = 'Error - Scripts Disabled';
            end
%             app.ResponseMessage(response==hex2dec('8F'),'Scripts Disabled');
        end

        % Menu selected function: ScriptsEnableMenu
        function ScriptsEnableMenuSelected(app, event)
            app.nnp.enterWaiting();            
            response=app.nnp.nmt(7, '8E'); %enable scripts
            if response==hex2dec('8E')
                 dmessage='Scripts Enabled';
                response=app.nnp.nmt(7,'B3',7); % Run startup script
                if response==hex2dec('B3')
                    dmessage='Scripts enabled and Startup Ran';
                    %tbc=[1,1,1];
                else
                    dmessage=['Scripts Enabled, but ',app.nnp.lastError];
                    %tbc=[1,1,.7];
                end
            else
                dmessage=app.nnp.lastError;
            end
            app.ErrorsTextArea.Value=dmessage;
          %  app.MessagesTextArea.BackgroundColor=tbc;
        end

        % Menu selected function: NeworkOnMenu
        function NeworkOnMenuSelected(app, event)
            if app.nnp.enterWaiting()
                if app.nnp.networkOn()
                    dmessage = 'Network On';
                    %tbc=[1,1,1];
                else
                    dmessage=app.nnp.lastError;
                   % tbc=[1,1,.7];
                end
            else
                dmessage=app.nnp.lastError;
                %tbc=[1,1,.7];
            end
            app.ErrorsTextArea.Value=dmessage;
%             app.ErrorsTextArea.BackgroundColor=tbc; 
        end

        % Menu selected function: NetworkOffMenu
        function NetworkOffMenuSelected(app, event)
            if app.nnp.enterWaiting()
                if app.nnp.networkOff()
                    dmessage = 'Network Off';
                    %tbc=[1,1,1];
                else
                    dmessage=app.nnp.lastError;
                   % tbc=[1,1,.7];
                end
            else
                dmessage=app.nnp.lastError;
                %tbc=[1,1,.7];
            end
            app.ErrorsTextArea.Value=dmessage;
%             app.ErrorsTextArea.BackgroundColor=tbc; 
        end

        % Menu selected function: WOROnMenu
        function WOROnMenuSelected(app, event)
           app.nnp.worOn(20);
            dmessage='WOR On';
            app.ErrorsTextArea.Value=dmessage;
        end

        % Menu selected function: WOROffMenu
        function WOROffMenuSelected(app, event)
             app.nnp.worOff;
            dmessage='WOR Off';
            app.ErrorsTextArea.Value=dmessage;
        end

        % Menu selected function: NumpadHelpMenu
        function NumpadHelpMenuSelected(app, event)
            %toggle numpad help on or off
            app.showNumpadImage=~app.showNumpadImage;
            app.NumPadHelpPanel.Visible=app.showNumpadImage;
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            app.showNumpadImage=~app.showNumpadImage;
            app.NumPadHelpPanel.Visible=app.showNumpadImage; 
        end

        % Button pushed function: ShowButton
        function ShowButtonPushed(app, event)
            app.showNumpadImage=~app.showNumpadImage;
            app.NumPadHelpPanel.Visible=app.showNumpadImage; 
        end

        % Button pushed function: Scpt11Button
        function Scpt11ButtonPushed(app, event)
            % Start script 11 to process the EMG
            % Need to enable the scripts first if they are not enabled
            resp=app.nnp.nmt(7,'8E');
            if ~resp==hex2dec('8E')           
                 dmessage='Enable scripts failed';
                 app.ErrorsTextArea.Value=dmessage;
                 return;
            end
            % Then start script 11
            app.nnp.nmt(7,'8D',11);
            if ~resp==hex2dec('8D')            
                 dmessage='Enable scripts failed';
                 app.ErrorsTextArea.Value=dmessage;
                 return;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Pattern Editor Keypad';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Text = 'Save As';
            app.SystemMenu = uimenu(app.UIFigure);
            app.SystemMenu.Text = 'System';
            app.GroupsMenu = uimenu(app.SystemMenu);
            app.GroupsMenu.MenuSelectedFcn = createCallbackFcn(app, @GroupsMenuSelected, true);
            app.GroupsMenu.Text = 'Groups';
            app.NodesMenu = uimenu(app.SystemMenu);
            app.NodesMenu.MenuSelectedFcn = createCallbackFcn(app, @NodesMenuSelected, true);
            app.NodesMenu.Text = 'Nodes';
            app.ChannelsMenu = uimenu(app.SystemMenu);
            app.ChannelsMenu.MenuSelectedFcn = createCallbackFcn(app, @ChannelsMenuSelected, true);
            app.ChannelsMenu.Text = 'Channels';
            app.LoadfromMATfileMenu = uimenu(app.SystemMenu);
            app.LoadfromMATfileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadfromMATfileMenuSelected, true);
            app.LoadfromMATfileMenu.Text = 'Load from MAT file';
            app.LoadCNGPfromNNPSFileMenu = uimenu(app.SystemMenu);
            app.LoadCNGPfromNNPSFileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadCNGPfromNNPSFileMenuSelected, true);
            app.LoadCNGPfromNNPSFileMenu.Text = 'Load CNGP from NNPS File';
            app.NetworkMenu = uimenu(app.UIFigure);
            app.NetworkMenu.Text = 'Network';
            app.NetworkOffMenu = uimenu(app.NetworkMenu);
            app.NetworkOffMenu.MenuSelectedFcn = createCallbackFcn(app, @NetworkOffMenuSelected, true);
            app.NetworkOffMenu.Text = 'Network Off';
            app.NeworkOnMenu = uimenu(app.NetworkMenu);
            app.NeworkOnMenu.MenuSelectedFcn = createCallbackFcn(app, @NeworkOnMenuSelected, true);
            app.NeworkOnMenu.Text = 'Nework On';
            app.ScriptsMenu = uimenu(app.UIFigure);
            app.ScriptsMenu.Text = 'Scripts';
            app.ScriptsDisableMenu = uimenu(app.ScriptsMenu);
            app.ScriptsDisableMenu.MenuSelectedFcn = createCallbackFcn(app, @ScriptsDisableMenuSelected, true);
            app.ScriptsDisableMenu.Text = 'Scripts Disable';
            app.ScriptsEnableMenu = uimenu(app.ScriptsMenu);
            app.ScriptsEnableMenu.MenuSelectedFcn = createCallbackFcn(app, @ScriptsEnableMenuSelected, true);
            app.ScriptsEnableMenu.Text = 'Scripts Enable';
            app.WORMenu = uimenu(app.UIFigure);
            app.WORMenu.Text = 'WOR';
            app.WOROffMenu = uimenu(app.WORMenu);
            app.WOROffMenu.MenuSelectedFcn = createCallbackFcn(app, @WOROffMenuSelected, true);
            app.WOROffMenu.Text = 'WOR Off';
            app.WOROnMenu = uimenu(app.WORMenu);
            app.WOROnMenu.MenuSelectedFcn = createCallbackFcn(app, @WOROnMenuSelected, true);
            app.WOROnMenu.Text = 'WOR On';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.HelpSubMenu = uimenu(app.HelpMenu);
            app.HelpSubMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpSubMenuSelected, true);
            app.HelpSubMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.CommunicationsIssuesMenu = uimenu(app.HelpMenu);
            app.CommunicationsIssuesMenu.MenuSelectedFcn = createCallbackFcn(app, @CommunicationsIssuesMenuSelected, true);
            app.CommunicationsIssuesMenu.Text = 'Communications Issues';
            app.NumpadHelpMenu = uimenu(app.HelpMenu);
            app.NumpadHelpMenu.MenuSelectedFcn = createCallbackFcn(app, @NumpadHelpMenuSelected, true);
            app.NumpadHelpMenu.Text = 'Numpad Help';
            app.ProfileMenu = uimenu(app.UIFigure);
            app.ProfileMenu.Text = 'Profile';
            app.LoadProfile = uimenu(app.ProfileMenu);
            app.LoadProfile.MenuSelectedFcn = createCallbackFcn(app, @LoadProfileMenuSelected, true);
            app.LoadProfile.Text = 'Load Profile';
            app.ExportMenu = uimenu(app.UIFigure);
            app.ExportMenu.Text = 'Export';
            app.SaveActiveFGMenu = uimenu(app.ExportMenu);
            app.SaveActiveFGMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveActiveFGMenuSelected, true);
            app.SaveActiveFGMenu.Text = 'SaveActiveFG';
            app.ExportActiveFGMenu = uimenu(app.ExportMenu);
            app.ExportActiveFGMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportActiveFGMenuSelected, true);
            app.ExportActiveFGMenu.Text = 'ExportActiveFG';
            app.ExportAllFGtoPNGXLSMenu = uimenu(app.ExportMenu);
            app.ExportAllFGtoPNGXLSMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportAllFGtoPNGXLSMenuSelected, true);
            app.ExportAllFGtoPNGXLSMenu.Text = 'Export All FG to PNG XLS';
            app.EditModeSwitchLabel = uilabel(app.UIFigure);
            app.EditModeSwitchLabel.Text = 'Edit Mode';
            app.EditModeSwitch = uiswitch(app.UIFigure, 'slider');
            app.EditModeSwitch.Items = {'Pattern Values', 'Breakpoints'};
            app.EditModeSwitch.ValueChangedFcn = createCallbackFcn(app, @EditModeSwitchValueChanged, true);
            app.EditModeSwitch.Tooltip = {'Right click '; 'plotted lines to'; 'add/remove'; 'breakpoints'};
            app.EditModeSwitch.Value = 'Pattern Values';
            app.StimulationPanel = uipanel(app.UIFigure);
            app.StimulationPanel.Title = 'Stimulation';
            app.StimONButton = uibutton(app.StimulationPanel, 'push');
            app.StimONButton.ButtonPushedFcn = createCallbackFcn(app, @StimONButtonPushed, true);
            app.StimONButton.Text = {'Stim'; 'ON'};
            app.StimOFFButton = uibutton(app.StimulationPanel, 'push');
            app.StimOFFButton.ButtonPushedFcn = createCallbackFcn(app, @StimOFFButtonPushed, true);
            app.StimOFFButton.Text = {'Stim'; 'OFF'};
            app.HighBandwidthCheckBox = uicheckbox(app.StimulationPanel);
            app.HighBandwidthCheckBox.Tooltip = {'PM and PG Stim Mapping PDOs must be configured for this functionality (enables one radio message to update all stim parameters)'};
            app.HighBandwidthCheckBox.Text = {'High Bandwidth'; '(Use PDOs)'};
            app.HighBandwidthCheckBox.Value = true;
            app.CommandLabel = uilabel(app.StimulationPanel);
            app.CommandLabel.Text = 'Command';
            app.CommandEditField = uieditfield(app.StimulationPanel, 'numeric');
            app.CommandEditField.Limits = [0 255];
            app.CommandEditField.RoundFractionalValues = 'on';
            app.CommandEditField.ValueChangedFcn = createCallbackFcn(app, @CommandEditFieldValueChanged, true);
            app.CommandfromPMCheckBox = uicheckbox(app.StimulationPanel);
            app.CommandfromPMCheckBox.ValueChangedFcn = createCallbackFcn(app, @CommandfromPMCheckBoxValueChanged, true);
            app.CommandfromPMCheckBox.Tooltip = {'Need to have script 11, Hand preprocess running.'};
            app.CommandfromPMCheckBox.Text = {'Command'; 'from PM'};
            app.SubindexEditFieldLabel = uilabel(app.StimulationPanel);
            app.SubindexEditFieldLabel.Enable = 'off';
            app.SubindexEditFieldLabel.Text = 'Subindex';
            app.SubindexEditField = uieditfield(app.StimulationPanel, 'numeric');
            app.SubindexEditField.Limits = [1 250];
            app.SubindexEditField.Enable = 'off';
            app.SubindexEditField.Tooltip = {'N7:1F53.38 (dec 57) is ASF2 - the output of MES2 filtered'};
            app.SubindexEditField.Value = 57;
            app.MuscleEditField = uieditfield(app.StimulationPanel, 'text');
            app.MuscleEditField.Editable = 'off';
            app.MuscleEditField.Value = 'none';
            app.PWEditField = uieditfield(app.StimulationPanel, 'numeric');
            app.PWEditField.Editable = 'off';
            app.PWLabel = uilabel(app.StimulationPanel);
            app.PWLabel.Text = 'PW';
            app.Scpt11Button = uibutton(app.StimulationPanel, 'push');
            app.Scpt11Button.ButtonPushedFcn = createCallbackFcn(app, @Scpt11ButtonPushed, true);
            app.Scpt11Button.Enable = 'off';
            app.Scpt11Button.Tooltip = {'Start script to process the EMG for command'};
            app.Scpt11Button.Text = {'Scpt'; '11'};
            app.PatternGroupDropDownLabel = uilabel(app.UIFigure);
            app.PatternGroupDropDownLabel.Text = 'Pattern Group';
            app.PatternGroupDropDown = uidropdown(app.UIFigure);
            app.PatternGroupDropDown.Items = {};
            app.PatternGroupDropDown.ValueChangedFcn = createCallbackFcn(app, @PatternGroupDropDownValueChanged, true);
            app.PatternGroupDropDown.Value = {};
            app.NodeDropDownLabel = uilabel(app.UIFigure);
            app.NodeDropDownLabel.Text = 'Node';
            app.NodeDropDown = uidropdown(app.UIFigure);
            app.NodeDropDown.Items = {};
            app.NodeDropDown.ValueChangedFcn = createCallbackFcn(app, @NodeDropDownValueChanged, true);
            app.NodeDropDown.Value = {};
            app.SystemPatternsPanel = uipanel(app.UIFigure);
            app.SystemPatternsPanel.Title = 'System Patterns';
            app.WriteButton = uibutton(app.SystemPatternsPanel, 'push');
            app.WriteButton.ButtonPushedFcn = createCallbackFcn(app, @WriteButtonPushed, true);
            app.WriteButton.Text = 'Write ';
            app.ReadButton = uibutton(app.SystemPatternsPanel, 'push');
            app.ReadButton.ButtonPushedFcn = createCallbackFcn(app, @ReadButtonPushed, true);
            app.ReadButton.Text = 'Read ';
            app.CompareButton = uibutton(app.SystemPatternsPanel, 'push');
            app.CompareButton.ButtonPushedFcn = createCallbackFcn(app, @CompareButtonPushed, true);
            app.CompareButton.Tooltip = {'read is not same order as write, so this needs to be corrrected'};
            app.CompareButton.Text = 'Compare ';
            app.CurrentOnlyCheckBox = uicheckbox(app.SystemPatternsPanel);
            app.CurrentOnlyCheckBox.Text = 'Current Pattern Group Only';
            app.EraseButton = uibutton(app.SystemPatternsPanel, 'push');
            app.EraseButton.ButtonPushedFcn = createCallbackFcn(app, @EraseButtonPushed, true);
            app.EraseButton.Text = 'Erase';
            app.LoadReadPatternsintoEditorButton = uibutton(app.SystemPatternsPanel, 'push');
            app.LoadReadPatternsintoEditorButton.ButtonPushedFcn = createCallbackFcn(app, @LoadReadPatternsintoEditorButtonPushed, true);
            app.LoadReadPatternsintoEditorButton.Text = 'Load Read Patterns into Editor ';
            app.Panel = uipanel(app.UIFigure);
            app.PatternTable = uitable(app.Panel);
            app.PatternTable.ColumnName = {''};
            app.PatternTable.RowName = {};
            app.PatternTable.ColumnEditable = [false false false false false false true false false false];
            app.PatternTable.CellEditCallback = createCallbackFcn(app, @PatternTableCellEdit, true);
            app.PatternTable.CellSelectionCallback = createCallbackFcn(app, @PatternTableCellSelection, true);
            app.ColorTable = uitable(app.Panel);
            app.ColorTable.ColumnName = {''};
            app.ColorTable.RowName = {};
            app.ColorTable.CellSelectionCallback = createCallbackFcn(app, @ColorTableCellSelection, true);
            app.PatternTableHeadings = uitable(app.Panel);
            app.PatternTableHeadings.ColumnName = {'Pattern Group'; 'Node'; 'Ch'; 'CmdID'; 'Min'; 'Max'; '_'; 'PW'; 'Amp'; 'Muscle'; 'Color'};
            app.PatternTableHeadings.RowName = {};
            app.AllMuteCheckBox = uicheckbox(app.Panel);
            app.AllMuteCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllMuteCheckBoxValueChanged, true);
            app.AllMuteCheckBox.Text = '';
            app.ButtonUp = uibutton(app.UIFigure, 'push');
            app.ButtonUp.ButtonPushedFcn = createCallbackFcn(app, @ButtonUpPushed, true);
            app.ButtonUp.Text = '▲';
            app.ButtonDown = uibutton(app.UIFigure, 'push');
            app.ButtonDown.ButtonPushedFcn = createCallbackFcn(app, @ButtonDownPushed, true);
            app.ButtonDown.Text = '▼';
            app.ButtonTop = uibutton(app.UIFigure, 'push');
            app.ButtonTop.ButtonPushedFcn = createCallbackFcn(app, @ButtonTopPushed, true);
            app.ButtonTop.Text = {'▬'; '▲'};
            app.ButtonBottom = uibutton(app.UIFigure, 'push');
            app.ButtonBottom.ButtonPushedFcn = createCallbackFcn(app, @ButtonBottomPushed, true);
            app.ButtonBottom.Text = {'▼'; '▬'};
            app.PatternValuesPanel = uipanel(app.UIFigure);
            app.PatternValuesPanel.Title = 'Pattern Values';
            app.PatternValuesTable = uitable(app.PatternValuesPanel);
            app.PatternValuesTable.ColumnName = {'cmd'; 'PW'; 'Amp'};
            app.PatternValuesTable.ColumnEditable = [true true true];
            app.PatternValuesTable.CellEditCallback = createCallbackFcn(app, @PatternValuesTableCellEdit, true);
            app.SetAllPWButton = uibutton(app.PatternValuesPanel, 'push');
            app.SetAllPWButton.ButtonPushedFcn = createCallbackFcn(app, @SetAllPWButtonPushed, true);
            app.SetAllPWButton.Text = 'PW';
            app.SetAllAmpButton = uibutton(app.PatternValuesPanel, 'push');
            app.SetAllAmpButton.ButtonPushedFcn = createCallbackFcn(app, @SetAllAmpButtonPushed, true);
            app.SetAllAmpButton.Text = 'Amp';
            app.AddChannelPatternButton = uibutton(app.UIFigure, 'push');
            app.AddChannelPatternButton.ButtonPushedFcn = createCallbackFcn(app, @AddChannelPatternButtonPushed, true);
            app.AddChannelPatternButton.Text = '+';
            app.RemoveChannelPatternButton = uibutton(app.UIFigure, 'push');
            app.RemoveChannelPatternButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveChannelPatternButtonPushed, true);
            app.RemoveChannelPatternButton.Text = '-';
            app.ErrorsTextAreaLabel = uilabel(app.UIFigure);
            app.ErrorsTextAreaLabel.Text = 'Communication Errors';
            app.ErrorsTextArea = uitextarea(app.UIFigure);
            app.DownloadableCheckBox = uicheckbox(app.UIFigure);
            app.DownloadableCheckBox.ValueChangedFcn = createCallbackFcn(app, @DownloadableCheckBoxValueChanged, true);
            app.DownloadableCheckBox.Text = 'Downloadable';
            app.KeyboardControlsPanel = uipanel(app.UIFigure);
            app.KeyboardControlsPanel.Tooltip = {'If keyboard controls are not active click on the plot'};
            app.KeyboardControlsPanel.Title = 'Keyboard Controls';
            app.Button = uibutton(app.KeyboardControlsPanel, 'push');
            app.Button.Tooltip = {'upon key release increase PW or PA (if ALT is pressed) for the selected channel pattern at the selected breakpoint by 1 or 10 (if SHIFT is pressed)'};
            app.Button.Text = '↑';
            app.Button_2 = uibutton(app.KeyboardControlsPanel, 'push');
            app.Button_2.Tooltip = {'upon key release decrease PW or PA (if ALT is pressed) for the selected channel pattern at selected  breakpoint by 1 or 10 (if SHIFT is pressed)'};
            app.Button_2.Text = '↓';
            app.Button_3 = uibutton(app.KeyboardControlsPanel, 'push');
            app.Button_3.Tooltip = {'move slider right (if stim is ON command updates on release)'};
            app.Button_3.Text = '→';
            app.Button_4 = uibutton(app.KeyboardControlsPanel, 'push');
            app.Button_4.Tooltip = {'move slider left (if stim is ON command updates on release)'};
            app.Button_4.Text = '←';
            app.SPACEButton = uibutton(app.KeyboardControlsPanel, 'push');
            app.SPACEButton.Tooltip = {'Space turns stim OFF'};
            app.SPACEButton.Text = 'SPACE';
            app.StimONOFFLabel = uilabel(app.KeyboardControlsPanel);
            app.StimONOFFLabel.Text = 'Stim ON +        OFF-';
            app.SPACECTRLButton = uibutton(app.KeyboardControlsPanel, 'push');
            app.SPACECTRLButton.Tooltip = {'Space+CTRL turns stim ON or OFF'};
            app.SPACECTRLButton.Text = 'SPACE+CTRL';
            app.Button_5 = uibutton(app.KeyboardControlsPanel, 'push');
            app.Button_5.Tooltip = {'go to nearest breakpoint to left'; '(if ALT pressed'; ' changes active channel pattern)'};
            app.Button_5.Text = ';';
            app.Button_6 = uibutton(app.KeyboardControlsPanel, 'push');
            app.Button_6.Tooltip = {'go to nearest breakpoint to right'; '(if ALT pressed'; ' changes active channel pattern)'};
            app.Button_6.Text = '''';
            app.BreakpointsLabel_2 = uilabel(app.KeyboardControlsPanel);
            app.BreakpointsLabel_2.Text = 'Breakpoints  ';
            app.CommandValueLabel = uilabel(app.KeyboardControlsPanel);
            app.CommandValueLabel.Text = 'Command Value';
            app.PWPAValueLabel = uilabel(app.KeyboardControlsPanel);
            app.PWPAValueLabel.Text = 'PW (PA) Value';
            app.ALTButton = uibutton(app.KeyboardControlsPanel, 'push');
            app.ALTButton.Tooltip = {'Change Pulse Amplitude instead of Pulse Width'};
            app.ALTButton.Text = '(+ALT)';
            app.PgDnButton = uibutton(app.KeyboardControlsPanel, 'push');
            app.PgDnButton.Tooltip = {'change by 10s instead of by 1s'};
            app.PgDnButton.Text = 'PgDn';
            app.ChannelLabel = uilabel(app.KeyboardControlsPanel);
            app.ChannelLabel.Text = 'Channel';
            app.ALTButton_2 = uibutton(app.KeyboardControlsPanel, 'push');
            app.ALTButton_2.Tooltip = {'Change current channel instead of current breakpoint'};
            app.ALTButton_2.Text = '+ALT';
            app.x10Label_2 = uilabel(app.KeyboardControlsPanel);
            app.x10Label_2.Text = '(x10)';
            app.PgUpButton = uibutton(app.KeyboardControlsPanel, 'push');
            app.PgUpButton.Tooltip = {'change by 10s instead of by 1s'};
            app.PgUpButton.Text = {'PgUp'; '>'};
            app.PgDnButton_2 = uibutton(app.KeyboardControlsPanel, 'push');
            app.PgDnButton_2.Tooltip = {'change by 10s instead of by 1s'};
            app.PgDnButton_2.Text = {'PgDn'; '<'};
            app.FastholdCTLInsertLabel = uilabel(app.KeyboardControlsPanel);
            app.FastholdCTLInsertLabel.Text = 'Fast - hold, CTL , Insert';
            app.ShowButton = uibutton(app.KeyboardControlsPanel, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowButtonPushed, true);
            app.ShowButton.Text = 'Show';
            app.clearButton = uibutton(app.UIFigure, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clearButton.Text = 'clear';
            app.Lamp = uilamp(app.UIFigure);
            app.OverrideNumlockCheckBox = uicheckbox(app.UIFigure);
            app.OverrideNumlockCheckBox.Text = 'Override Numlock';
            app.OverrideNumlockCheckBox.Value = true;
            app.GraphTabGroup = uitabgroup(app.UIFigure);
            app.PWTab = uitab(app.GraphTabGroup);
            app.PWTab.Title = 'PW';
            app.PWAxes = uiaxes(app.PWTab);
            title(app.PWAxes, 'Title')
            xlabel(app.PWAxes, 'Command Level')
            ylabel(app.PWAxes, 'Y')
            app.PWAxes.Visible = 'off';
            app.NumPadHelpPanel = uipanel(app.PWTab);
            app.NumPadHelpPanel.Title = 'Num Pad Panel';
            app.NumPadHelpPanel.Visible = 'off';
            app.Image = uiimage(app.NumPadHelpPanel);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'numpadPatternEdit.png';
            app.AmpTab = uitab(app.GraphTabGroup);
            app.AmpTab.Title = 'Amp';
            app.PAAxes = uiaxes(app.AmpTab);
            xlabel(app.PAAxes, 'Command Level')
            ylabel(app.PAAxes, 'Y')
            app.PAAxes.Visible = 'off';
            app.StimModeSwitchLabel = uilabel(app.UIFigure);
            app.StimModeSwitchLabel.Text = 'Stim Mode';
            app.StimModeSwitch = uiswitch(app.UIFigure, 'slider');
            app.StimModeSwitch.Items = {'Downloaded', 'Displayed'};
            app.StimModeSwitch.ValueChangedFcn = createCallbackFcn(app, @StimModeSwitchValueChanged, true);
            app.StimModeSwitch.Tooltip = {'Disable scripts before using downloaded option'};
            app.StimModeSwitch.Value = 'Displayed';
            app.UserIDEditField = uieditfield(app.UIFigure, 'text');
            app.UserIDEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = patternEditKeyPad(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end