classdef patternedit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FIleMenu                      matlab.ui.container.Menu
        OpenMenu                      matlab.ui.container.Menu
        SaveMenu                      matlab.ui.container.Menu
        SaveAsMenu                    matlab.ui.container.Menu
        GroupsMenu                    matlab.ui.container.Menu
        ChannelsMenu                  matlab.ui.container.Menu
        HelpMenu                      matlab.ui.container.Menu
        UIAxes                        matlab.ui.control.UIAxes
        EditModeSwitchLabel           matlab.ui.control.Label
        EditModeSwitch                matlab.ui.control.Switch
        ViewModeSwitch                matlab.ui.control.Switch
        StimulationPanel              matlab.ui.container.Panel
        StimONButton                  matlab.ui.control.Button
        StimOFFButton                 matlab.ui.control.Button
        StimModeSwitchLabel           matlab.ui.control.Label
        StimModeSwitch                matlab.ui.control.Switch
        HighBandwidthUsePDOsCheckBox  matlab.ui.control.CheckBox
        CommandValueEditFieldLabel    matlab.ui.control.Label
        CommandValueEditField         matlab.ui.control.NumericEditField
        PatternGroupDropDownLabel     matlab.ui.control.Label
        PatternGroupDropDown          matlab.ui.control.DropDown
        ModuleDropDownLabel           matlab.ui.control.Label
        ModuleDropDown                matlab.ui.control.DropDown
        SystemPatternsPanel           matlab.ui.container.Panel
        WritetoModulesButton          matlab.ui.control.Button
        ReadfromModulesButton         matlab.ui.control.Button
        CompareModulesButton          matlab.ui.control.Button
        CurrentModuleOnlyCheckBox     matlab.ui.control.CheckBox
        Panel                         matlab.ui.container.Panel
        PatternTable                  matlab.ui.control.Table
        ColorTable                    matlab.ui.control.Table
        PatternTableHeadings          matlab.ui.control.Table
        ButtonUp                      matlab.ui.control.Button
        ButtonDown                    matlab.ui.control.Button
        ButtonTop                     matlab.ui.control.Button
        ButtonBottom                  matlab.ui.control.Button
        UIAxes2                       matlab.ui.control.UIAxes
        PatternValuesPanel            matlab.ui.container.Panel
        PatternValuesTable            matlab.ui.control.Table
        AllShowCheckBox               matlab.ui.control.CheckBox
        AllMuteCheckBox               matlab.ui.control.CheckBox
        AddChannelPatternButton       matlab.ui.control.Button
        RemoveChannelPatternButton    matlab.ui.control.Button
    end

    
    properties (Access = public)
        editMode = 'x'; % Description
        Plots = [];
        SmallPlots = [];
        PlotBars = [];
        PlotSlider = [];
        PlotSliderSmall = [];
        colorOrder = [ 0.6350    0.0780    0.1840
                       1.0000         0         0
                       0.8500    0.3250    0.0980
                       0.9290    0.6940    0.1250
                       0.4660    0.6740    0.1880
                       0.3010    0.7450    0.9330
                       0    0.4470    0.7410
                       0.4940    0.1840    0.5560];
       markerOrder = 'osv^ph';
       markerOrderLabel = '●■▼▲►◄'
       Patterns = [];
       ActivePatterns = [];
       UnselectedColor;
       activePatternRow;
       nnps =[];
       Y = [];
    end
    
    properties (Access = private)

    end


    methods (Access = public)
        
        function SliderClickCallback(app, src, evt )
            disp('Clicked Slider')
            hLine = src;
            ax = src.Parent;
            app.UIFigure.WindowButtonMotionFcn = {@app.MovePointCallback, ax, hLine, 'Slider'}; 
            app.UIFigure.WindowButtonUpFcn = {@app.ReleaseClickCallback,ax, hLine, 'Slider'};
        end
        
        function LineClickCallback(app, src, evt )
            %LINECLICK Called on a click of a pattern or breakpoint line
            %
            % If 'HitTest' is set to 'on' clicking the line will trigger this callback
            % (not sure if threshold for click can be adjusted.  It doesn't seem
            % affected by line thickness)
            %  HitTest      pattern lines    breakpoint lines
            %  y-edit mode  ON               OFF
            %  x-edit mode  OFF              ON
            %
            % src is the handle of the clicked line
            %       (pattern in y-edit mode OR breakpoint in x-edit mode)
            % evt is used to find the clicked point
            %
            %  The following inputs are only required for x-edit mode since all lines
            %  need to be updated when changing x-value, but are also passed during
            %  y-edit mode for simplicity:
            % hPlots are handles to all the Patterns plotted
            % hPlotBars are handles to all the Breakpoint lines
            %
            % Note:
            % axis CurrentPoint reports non NaN values when outside of plot area
            % figure CurrentPoint reports values in figure pixels, not axis units
            % evt.IntersectionPoint reports NaN values when outside plot area
            
            
            breakPointThreshold = 4; %if this is too low, we may create a new
            %breakpoint at the same time that we remove one
            
            xc = evt.IntersectionPoint(1);
            yc = evt.IntersectionPoint(2);
            hLine = src;
            ax = src.Parent;
            
            % Callback entered on line click. Find closest point on the line
            switch app.editMode
                case 'y'
                    y=abs(hLine.YData-yc);
                    x=abs(hLine.XData-xc);
                    d=y.^2+x.^2;
                    index=find(d==min(d));
                    
                    
                    %update PatternValues Table and PatternTable Selction
                    if ax == app.UIAxes
                        row = find(cellfun(@(x) x==hLine, app.Plots));
                    elseif ax == app.UIAxes2
                        row = find(cellfun(@(x) x==hLine, app.SmallPlots));
                    end
                    if isempty(row)
                        warning('Could not determine active pattern')
                    else
                        disp(['click on pattern row:' num2str(row)])
                        app.updateActivePattern(row);
                    end


                case 'x'
                    switch evt.Button
                        case 1 %left click
                            index = find(cellfun(@(x) x==hLine, app.PlotBars))+1; 
                        case 3 %right click
                            
                            index = find(cellfun(@(x) x==hLine, app.PlotBars))+1; %find breakpoint that matches current line handle
                            if isempty(index) %line handle must be a pattern line
                                xc = round(xc);
                                if any(abs(app.Plots{1}.XData-xc)<breakPointThreshold)
                                    disp('Breakpoint already exists at this value')
                                    return;
                                end
                                %insert the new breakpoint
                                before = app.Plots{1}.XData<xc;
                                after  = app.Plots{1}.XData>xc;
                                xbefore = app.Plots{1}.XData(before);
                                xafter = app.Plots{1}.XData(after);
                                for i=1:length(app.Plots)
                                    ybefore = app.Plots{i}.YData(before);
                                    yafter = app.Plots{i}.YData(after);
                                    %interpolate new y value
                                    yc = round(ybefore(end) + (xc-xbefore(end))*(yafter(1)-ybefore(end))/(xafter(1)-xbefore(end)));
                                    app.Plots{i}.XData = [xbefore, xc, xafter];
                                    app.Plots{i}.YData = [ybefore, yc, yafter];
                                end
                                for i=1:length(app.SmallPlots)
                                    ybefore = app.SmallPlots{i}.YData(before);
                                    yafter = app.SmallPlots{i}.YData(after);
                                    %interpolate new y value
                                    yc = round(ybefore(end) + (xc-xbefore(end))*(yafter(1)-ybefore(end))/(xafter(1)-xbefore(end)));
                                    app.SmallPlots{i}.XData = [xbefore, xc, xafter];
                                    app.SmallPlots{i}.YData = [ybefore, yc, yafter];
                                end
                                index = length(xbefore);
                                for i=length(app.PlotBars):-1:index+1
                                    app.PlotBars{i}.XData = app.PlotBars{i-1}.XData;
                                end
                                app.PlotBars{index}.XData = [xc xc];
                            else
                                resp = questdlg('Delete Breakpoint?');
                                if strcmp(resp, 'Yes')
                                    for i=1:length(app.Plots)
                                        app.Plots{i}.XData = [app.Plots{i}.XData(1:index-1) app.Plots{i}.XData(index+1:end)];
                                        app.Plots{i}.YData = [app.Plots{i}.YData(1:index-1) app.Plots{i}.YData(index+1:end)];
                                    end
                                    for i=1:length(app.SmallPlots)
                                        app.SmallPlots{i}.XData = [app.SmallPlots{i}.XData(1:index-1) app.SmallPlots{i}.XData(index+1:end)];
                                        app.SmallPlots{i}.YData = [app.SmallPlots{i}.YData(1:index-1) app.SmallPlots{i}.YData(index+1:end)];
                                    end
                                    for i=index-1:length(app.PlotBars)-2
                                        app.PlotBars{i}.XData = app.PlotBars{i+1}.XData;
                                    end
                                end
                            end
                            return;%don't start the Move CallBack
                    end
            end
            app.UIFigure.WindowButtonMotionFcn = {@app.MovePointCallback, ax, hLine, index}; 
            app.UIFigure.WindowButtonUpFcn = {@app.ReleaseClickCallback, ax, hLine, index};
        end

        function MovePointCallback(app, src, evt, ax, line, i)
            %MOVEPOINTCALLBACK Summary of this function goes here
            %   Detailed explanation goes here
            
            %src is Figure Handle
            %evt is currently unused
            %line is the handle of the clicked line
            %       (pattern in y-edit mode OR breakpoint in x-edit mode)
            %
            % This callback is enabled by the LineClickCallBack and disabled
            % by the ReleaseClickCallback
            %
            %Note:
            %axis CurrentPoint reports non NaN values when outside of plot area
            %figure CurrentPoint reports values in figure pixels, not axis units
            %evt.IntersectionPoint reports NaN values when outside plot area


            if isequal(i, 'Slider')
                xc = round(ax.CurrentPoint(1,1));
                xc = max(xc, 0);
                xc = min(xc, 255);
                line.XData = [xc xc];
                app.PlotSliderSmall.XData = [xc xc];
                app.CommandValueEditField.Value = xc;
                return;
            elseif length(i)~=1
                return
            end
            
            switch app.editMode
                case 'y' %line selected is a pattern line
                   % if toc > 0.1
                        
                        yc = round(ax.CurrentPoint(1,2));
                        %limit yc between 0 and ax.YLim(2)
                        yc = max(yc, 0);
                        yc = min(yc, ax.YLim(2));

                        
                        app.Y = [app.Y  yc];
                        %change YData for single Line
                        line.YData(i) = yc;
                        disp(['move point value' num2str(yc)])
                        
                   % end
                   % tic
                    
                    drawnow %limitrate nocallbacks
                    
                    %update PatternValuesTable in ReleaseClick


                case 'x' %line selected is a breakpoint line
                    %disp('move breakpoint')
                    xc = round(ax.CurrentPoint(1,1));
                    if i > 1 && i < length(app.Plots{1}.XData)
                        %limit xc between neighboring breakpoint values
                        xc = max(xc, app.Plots{1}.XData(i-1)+1);
                        xc = min(xc, app.Plots{1}.XData(i+1)-1);
                        %change XData for all patterns
                        for j=1:length(app.Plots)
                            app.Plots{j}.XData(i) = xc;
                        end
                        for j=1:length(app.SmallPlots)
                            app.SmallPlots{j}.XData(i) = xc;
                        end
                        %change XData for breakpoint line
                        line.XData = [xc xc];
                    end
            end
        end
    
        function ReleaseClickCallback(app, src, evt, ax, line, i )
            %RELEASECLICKCALLBACK called when mouse button is released
            % responsible for disabling the mouse movment callback, so it only occurs
            % while mouse button is pressed
            %
            % src is figure handle
            % evt is currently unused
            %
            % This callback is enabled by the LineClickCallBack and disabled
            % by the ReleaseClickCallback (itself)
            src.WindowButtonMotionFcn = [];
            src.WindowButtonUpFcn = [];
   
            if isequal(i, 'Slider')
                return;
            end
            disp('Release Click')
            switch app.editMode
                case 'y'
                    yc = line.YData(i);
                    if ax==app.UIAxes
                        app.PatternValuesTable.Data(i,2) = yc;
                    elseif ax == app.UIAxes2
                        app.PatternValuesTable.Data(i,3) = yc;
                    end
                case 'x'
                    app.PatternValuesTable.Data(i,1) = line.XData(1);
            end
            
            %JML TODO: Need to update stored pattern values
            
        end
        
    end
    
    methods (Access = private)
        
        function initAxes(app, ax, breakpoints, y, maxY, main)
            nMaxBreakPoints=20;
            nPatterns = size(y,1);    
            
            ax.Visible = 'off';
            
            cla(ax);
            hold(ax,'on');
            
             
            if main
                app.Plots = cell(nPatterns,1);
                for i=1:nPatterns
                    app.Plots{i} = plot(ax, breakpoints, y(i,:));
                end
                
            
                %we will only change XData and YData in the callback functions so we need
                %to have all the handles to possible plots already assigned.  Since
                %breakpoints can be added and removed, we will already allocate all 20
                %possible breakpoints, and just use NaNs as x values to make them
                %invisible.  We won't draw vertical bars for the first and last breakpoints
                app.PlotBars = cell(nMaxBreakPoints-2,1);
                for i=2:nMaxBreakPoints-1
                    if i<length(breakpoints)
                        x = [breakpoints(i) breakpoints(i)];
                    else
                        x = [NaN NaN];
                    end
                    app.PlotBars{i-1} = plot(ax, x, [0 maxY]);
                end
                app.PlotSlider = plot(ax, [20 20], [0 maxY], 'LineWidth', 4, 'Color', [1, 0.5, 0]);
            else
                app.SmallPlots = cell(nPatterns,1);
                for i=1:nPatterns
                    app.SmallPlots{i} = plot(ax, breakpoints, y(i,:));
                end
                app.PlotSliderSmall = plot(ax, [20 20], [0 maxY], 'LineWidth', 4, 'Color', [1, 0.5, 0]);
            end
            hold(ax,'off');
            
            axis(ax, [0 255 0 maxY]);
            %xlabel(ax, ['Command (' num2str(cmdID) ')']);
            if maxY == 255
                ylabel(ax, 'Pulse Width (us)')
            else
                ylabel(ax, 'Amplitude (mA)')
            end
            
            %title(ax, ['Group' num2str(group)])
            ax.XMinorTick = 'on';
            ax.YMinorTick = 'on';
            ax.XGrid = 'on';
            ax.YGrid = 'on';
            ax.XMinorGrid = 'on';
            ax.YMinorGrid = 'on';
            ax.Toolbar.Visible = 'off';  %make the little toolbar at top right disappear.
            ax.Interactions = [dataTipInteraction];
            
            if main
                for i=1:length(app.Plots)
                    m = floor((i-1)/8)+1; %marker index
                    c = mod((i-1),8)+1;      %color index
                    app.Plots{i}.ButtonDownFcn = {@app.LineClickCallback};
                    app.Plots{i}.Marker = app.markerOrder(m);
                    app.Plots{i}.Color = app.colorOrder(c,:);
                    app.Plots{i}.MarkerFaceColor = app.Plots{i}.Color;
                    app.Plots{i}.MarkerEdgeColor = 'none';%app.Plots{i}.Color;
                    app.Plots{i}.LineWidth = 2;
                    %app.Plots{i}.HitTest = 'on';
                end
                
                    for i=1:length(app.PlotBars)
                        app.PlotBars{i}.ButtonDownFcn = {@app.LineClickCallback};
                        app.PlotBars{i}.LineWidth = 2;
                        app.PlotBars{i}.Color = 'k';
                        %app.PlotBars{i}.LineStyle = ':';
                        %app.PlotBars{i}.HitTest = 'on';
                    end
                    app.PlotSlider.ButtonDownFcn = {@app.SliderClickCallback};
            else
                for i=1:length(app.SmallPlots)

                    app.SmallPlots{i}.ButtonDownFcn = {@app.LineClickCallback};
                    app.SmallPlots{i}.Marker = app.Plots{i}.Marker;
                    app.SmallPlots{i}.Color = app.Plots{i}.Color;
                    app.SmallPlots{i}.MarkerFaceColor = app.SmallPlots{i}.Color;
                    app.SmallPlots{i}.MarkerEdgeColor = 'none';
                    app.SmallPlots{i}.LineWidth = 2;
                end
            end
            
            EditModeSwitchValueChanged(app, 0);
            
            
            %disable tooltips on graphs
            %ShowToolTipsCheckBoxValueChanged(app, 0);
            disableDefaultInteractivity(app.UIAxes);
            disableDefaultInteractivity(app.UIAxes2);
                
            ax.Visible = 'on';
        
            
        end
        
        function setActivePatterns(app, p)
            app.ActivePatterns = app.Patterns(p);
            
            n = size(app.ActivePatterns,1); %number of active patterns
            d = cell(n,11);
            for i=1:n
                group = app.ActivePatterns{i}.group;
                node = app.ActivePatterns{i}.node;
                ch = app.ActivePatterns{i}.ch;
                d{i, 1} = ['#' num2str(group) ' : ' app.nnps.groups{group}.name]; %group name
                d{i, 2} = node;
                d{i, 3} = ch;
                d{i, 4} = app.ActivePatterns{i}.cmdID;
                d{i, 7} = false; %TODO change
                d{i, 8} = false; %TODO change
                d{i, 9} = '-';  %PW
                d{i,10} = '-';  %PA
                d{i,11} = app.nnps.channels{node, ch}.name; %muscle name
            end

            app.PatternTable.Data = d;
            
            
            d = cell(n,1);
            for i=1:n
                m = floor((i-1)/8)+1;
                d{i} = app.markerOrderLabel(m);
            end
            app.ColorTable.Data = d;
            app.ColorTable.BackgroundColor = app.colorOrder;
            
            cmd = cellfun(@(x)x.cmd,app.ActivePatterns, 'UniformOutput', false);
            pw = cellfun(@(x)x.pw,app.ActivePatterns, 'UniformOutput', false);
            amp = cellfun(@(x)x.amp,app.ActivePatterns, 'UniformOutput', false);
            
            breakpoints = unique(cell2mat(cmd), 'rows');
            
            if size(breakpoints, 1) > 1
                warning('all plotted patterns must have same breakpoints')
                return;
            end
            %initAxes(app, ax, breakpoints, y, maxY, main)
            app.initAxes(app.UIAxes, breakpoints, cell2mat(pw), 255, true);
            app.initAxes(app.UIAxes2, breakpoints, cell2mat(amp), 200, false);
        end
        
        function CellSelectionCallback(app, src, evt)
            row = evt.Indices(1);
            app.updateActivePattern(row);
        end
        
        function PatternValueChanged(app, src, evt)
            disp('PatternValueChange');
            row = evt.Indices(1);
            col = evt.Indices(2);
            switch (col)
                case 1 %Breakpoint (changes all patterns)
                    nrows = size(app.PatternValuesTable.Data, 1);
                    breakpoints = app.PatternValuesTable.Data(:,1);

                    % cannot change first or last breakpoint, or change a breakpoint outside range of neighboring breakpoints 
                    if row>1 && row<nrows && evt.NewData < breakpoints(row+1) && evt.NewData > breakpoints(row-1)
                        for i=1:length(app.Plots)
                            app.Plots{i}.XData(row) = evt.NewData;
                            app.SmallPlots{i}.XData(row) = evt.NewData;
                        end
                        app.PlotBars{row-1}.XData = [evt.NewData evt.NewData];
                    else
                        app.PatternValuesTable.Data(row, col) = evt.PreviousData;
                    end

                case 2 %PW
                    if evt.NewData <= 255 && evt.NewData >= 0
                        app.Plots{app.activePatternRow}.YData(row) = floor(evt.NewData); %assumes large plot is PW
                    else
                        app.PatternValuesTable.Data(row, col) = evt.PreviousData;
                    end
                case  3 %Amp
                    if evt.NewData <= 255 && evt.NewData >= 0
                        app.SmallPlots{app.activePatternRow}.YData(row) = floor(evt.NewData); %assumes small plot is Amp
                    else
                        app.PatternValuesTable.Data(row, col) = evt.PreviousData;
                    end
            end
            
        end
        
        function updateActivePattern(app, row)
            disp('UpdateActivePattern')
            %highlight active pattern in PatternTable
            app.activePatternRow = row;
            bg = app.UnselectedColor;
            bg(row,:) = [1 1 0]; %yellow
            app.PatternTable.BackgroundColor = bg;
            
            app.PatternValuesTable.Data = [app.ActivePatterns{row}.cmd 
                                           app.ActivePatterns{row}.pw 
                                           app.ActivePatterns{row}.amp]';

            %set all linewidths to normal                           
            for i=1:size(app.Plots, 1)
                app.Plots{i}.LineWidth = 2;
                app.Plots{i}.MarkerSize = 6;
                app.SmallPlots{i}.LineWidth = 2;
                app.SmallPlots{i}.MarkerSize = 6;
            end
            %set selected pattern linewidth/marker size
            app.Plots{row}.LineWidth = 4;
            app.Plots{row}.MarkerSize = 12;
            app.SmallPlots{row}.LineWidth = 4;
            app.SmallPlots{row}.MarkerSize = 12;
            
            %move active plot above others so easier to click
            moveToTop(app, app.UIAxes, app.Plots{row});
            moveToTop(app, app.UIAxes2, app.SmallPlots{row});
        end
        

        function moveToTop(app, axes, line)
        %move_to_bottom Moves the desired object to the bottom of the stack.
        %   For use with appdesigner objects where uistack no longer works.
       
           
            all_handles = axes.Children;
            
            if (numel(all_handles) < 2)
                return
            end
            
            all_indices = (1:numel(all_handles))';
            
            for index = 1:numel(line)
                if ishandle(line(index))
                    this_index = find(all_handles == line(index));
        
                    if ~isempty(this_index)
                        rest_of_indices = setdiff(all_indices, this_index);
                        all_handles = all_handles([this_index; rest_of_indices]);
                    end
                end
            end
            
            axes.Children = all_handles;
        end
        
        function clearActivePatterns(app)
            app.initAxes(app.UIAxes, [0 255], [NaN NaN], 255, true);
            app.initAxes(app.UIAxes2, [0 255], [NaN NaN], 200, false);
            
            app.PatternTable.Data = {};
                
            app.UIAxes.Title.String = 'No Pattern Group or Module Selected';
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             breakpoints = [0 100 200 255];
%             
%             y = [100 20 40  50
%                 255 120 40 100
%                 30  40 50 70 ];
% 
%             cmdID = 1;
%             group =2;
%             
%             
%             
            app.clearActivePatterns();
            
            app.PatternTable.CellSelectionCallback={@app.CellSelectionCallback};
            stripe = [ 1.0000    1.0000    1.0000
                       0.9400    0.9400    0.9400];
            app.PatternTable.BackgroundColor = repmat(stripe, 24, 1); %support up to 48 rows in table
            app.UnselectedColor = app.PatternTable.BackgroundColor;
            
            app.PatternValuesTable.CellEditCallback = {@app.PatternValueChanged};
            app.UIFigure.GraphicsSmoothing = 'on';
            
            app.nnps = load('nnps.mat'); %JML TODO: eventually this should load a specific participant file
        end

        % Value changed function: EditModeSwitch
        function EditModeSwitchValueChanged(app, event)
            if isequal(app.EditModeSwitch.Value, 'Breakpoints')
                disp('Edit X')
                app.editMode = 'x';
                for i=1:length(app.PlotBars)
                    app.PlotBars{i}.LineStyle = ':';
                    app.PlotBars{i}.HitTest = 'on'; 
                end
                for i=1:length(app.Plots)
                    app.Plots{i}.HitTest = 'on';
                end
                for i=1:length(app.SmallPlots)
                    app.SmallPlots{i}.HitTest = 'off';
                end
            else
                disp('Edit Y')
                app.editMode = 'y';
                for i=1:length(app.PlotBars)
                    app.PlotBars{i}.LineStyle = 'none';
                    app.PlotBars{i}.HitTest = 'off';
                end
                for i=1:length(app.Plots)
                    app.Plots{i}.HitTest = 'on';
                end
                for i=1:length(app.SmallPlots)
                    app.SmallPlots{i}.HitTest = 'on';
                end
            end
        end

        % Callback function
        function ShowToolTipsCheckBoxValueChanged(app, event)
            if app.ShowToolTipsCheckBox.Value
                enableDefaultInteractivity(app.UIAxes);
                enableDefaultInteractivity(app.UIAxes2);
            else
                disableDefaultInteractivity(app.UIAxes);
                disableDefaultInteractivity(app.UIAxes2);
            end
            
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            [filename, filepath] = uigetfile('.mat', 'Select Patterns File');
            if filename
                ld = load([filepath filename]);
            end
            
            p = ld.Patterns; %JML TODO: 
            n = size(p,1); %number of patterns
            
            nodes = unique(cellfun(@(x) x.node, p));
            groups = unique(cellfun(@(x) x.group, p));
            
            
            app.ModuleDropDown.Items = [{'-'}; arrayfun(@num2str, nodes, 'UniformOutput',false)];
            app.PatternGroupDropDown.Items = [{'-'}; cellfun(@(x) x.name, app.nnps.groups(groups), 'UniformOutput', false)]; 
            app.PatternGroupDropDown.ItemsData = [-1;  groups];
            app.Patterns = p;
           
        end

        % Button pushed function: ButtonUp
        function ButtonUpPushed(app, event)
            app.PatternTable.Position(2) = max(app.PatternTable.Position(2)-100, -636);
            app.ColorTable.Position(2) = app.PatternTable.Position(2);
        end

        % Button pushed function: ButtonDown
        function ButtonDownPushed(app, event)
            app.PatternTable.Position(2) = min(app.PatternTable.Position(2)+100, 0);
            app.ColorTable.Position(2) = app.PatternTable.Position(2);
        end

        % Button pushed function: ButtonTop
        function ButtonTopPushed(app, event)
            app.PatternTable.Position(2) = -636;
            app.ColorTable.Position(2) = app.PatternTable.Position(2);
        end

        % Button pushed function: ButtonBottom
        function ButtonBottomPushed(app, event)
            app.PatternTable.Position(2) = 0;
            app.ColorTable.Position(2) = app.PatternTable.Position(2);
        end

        % Value changed function: PatternGroupDropDown
        function PatternGroupDropDownValueChanged(app, event)
            currentGroup = app.PatternGroupDropDown.Value; 
            
            if currentGroup == -1

                app.clearActivePatterns();
                
                return;
            end
            
            p = cellfun(@(x) x.group == currentGroup, app.Patterns);
            
            app.setActivePatterns(p);
            app.UIAxes.Title.String = ['Pattern Group #' num2str(currentGroup) ' : ' app.nnps.groups{currentGroup}.name];
            
            app.updateActivePattern(1);  %default to first row in table is active
        end

        % Value changed function: ModuleDropDown
        function ModuleDropDownValueChanged(app, event)
            currentNode = str2double(app.ModuleDropDown.Value); 
            p = cellfun(@(x) x.node == currentNode, app.Patterns);
            app.setActivePatterns(p);
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            switch event.Key
                case 'leftarrow'
                    %jump slider to previous breakpoint
                    cmd = app.CommandValueEditField.Value;
                    i = find(app.Plots{1}.XData<cmd, 1, 'last');
                    
                    if ~isempty(i)
                        x = app.Plots{1}.XData(i);
                        app.PlotSlider.XData = [x x];
                        app.PlotSliderSmall.XData = [x x];
                        app.CommandValueEditField.Value = x;
                    end
                                
                case 'rightarrow'
                    %jump slider to next breakpoint
                    cmd = app.CommandValueEditField.Value;
                    i = find(app.Plots{1}.XData>cmd, 1, 'first');
                    
                    if ~isempty(i)
                        x = app.Plots{1}.XData(i);
                        app.PlotSlider.XData = [x x];
                        app.PlotSliderSmall.XData = [x x];
                        app.CommandValueEditField.Value = x;
                    end
            end
        end

        % Value changed function: CommandValueEditField
        function CommandValueEditFieldValueChanged(app, event)
            x = app.CommandValueEditField.Value;
            app.PlotSlider.XData = [x x];
            app.PlotSliderSmall.XData = [x x];     
        end

        % Cell edit callback: PatternTable
        function PatternTableCellEdit(app, event)
            indices = event.Indices;
            row = indices(1);
            col = indices(2);
            
            if col == 8 %Mute
                if event.NewData 
                    %Muted (dashed)
                    app.Plots{row}.LineStyle = ':';
                    app.SmallPlots{row}.LineStyle = ':';
                else 
                    %Unmuted (solid)
                    app.Plots{row}.LineStyle = '-';
                    app.SmallPlots{row}.LineStyle = '-';
                end
            end

            
        end

        % Value changed function: AllShowCheckBox
        function AllShowCheckBoxValueChanged(app, event)
            show = cell2mat(app.PatternTable.Data(:,7));
            if all(show)
                app.AllShowCheckBox.Value = false;
                show(:) = false;
            else
                app.AllShowCheckBox.Value = true;
                show(:) = true;
            end
            
            app.PatternTable.Data(:,7) = mat2cell(show, ones(length(show),1));
        end

        % Value changed function: AllMuteCheckBox
        function AllMuteCheckBoxValueChanged(app, event)
            mute = cell2mat(app.PatternTable.Data(:,8));
            if all(mute)
                app.AllMuteCheckBox.Value = false;
                mute(:) = false;
                
                for row=1:length(app.Plots)
                    %Unmuted (solid)
                    app.Plots{row}.LineStyle = '-';
                    app.SmallPlots{row}.LineStyle = '-';
                end
            else
                app.AllMuteCheckBox.Value = true;
                mute(:) = true;
                
                for row=1:length(app.Plots)
                    %Muted (dashed)
                    app.Plots{row}.LineStyle = ':';
                    app.SmallPlots{row}.LineStyle = ':';
                end
            end
            
            app.PatternTable.Data(:,8) = mat2cell(mute, ones(length(mute),1));
        end

        % Menu selected function: GroupsMenu
        function GroupsMenuSelected(app, event)
            groupedit(app)
        end

        % Button pushed function: AddChannelPatternButton
        function AddChannelPatternButtonPushed(app, event)
            resp = inputdlg({'node', 'channel'});
            
            if length(resp)~=2
                return;
            end
            node = str2double(resp{1});
            ch = str2double(resp{2});
            
            if length(node)~=1 || isnan(node) || node<=0 || node>15 || ...
                length(ch)~=1  || isnan(ch)   || ch<1    || ch>4
                warning('invalid node/ch entry')
                return;
            end
            
            i=length(app.Patterns)+1;
            
            %shared parameters for new pattern can be copied from other
            %pattern in group
            group = app.ActivePatterns{1}.group;
            cmdID = app.ActivePatterns{1}.cmdID;
            cmd = app.ActivePatterns{1}.cmd;
            n = app.ActivePatterns{1}.n;
            
            p = cellfun(@(x) x.group == group && x.node == node && x.ch == ch, app.Patterns);
            if any(p)
                warning('node/ch combination already exists for this pattern group')
                return;
            end
            
            app.Patterns{i}.group = group;
            app.Patterns{i}.node  = node;
            app.Patterns{i}.ch    = ch;
            app.Patterns{i}.cmdID = cmdID;
            app.Patterns{i}.n     = n;
            app.Patterns{i}.cmd   = cmd;
            app.Patterns{i}.pw    = zeros(1,n);
            app.Patterns{i}.amp   = ones(1,n)*100;

            
            %re-sort all patterns by group, node, ch
            M = [cellfun(@(x) x.group, app.Patterns), ...
                 cellfun(@(x) x.node, app.Patterns), ...
                 cellfun(@(x) x.ch, app.Patterns)];
            [~,i] = sortrows(M);
            app.Patterns = app.Patterns(i);

            p = cellfun(@(x) x.group == group, app.Patterns);
            
            app.setActivePatterns(p)
            
            psel = cellfun(@(x) x.node == node && x.ch == ch, app.ActivePatterns);
            app.updateActivePattern(psel);
        end

        % Button pushed function: RemoveChannelPatternButton
        function RemoveChannelPatternButtonPushed(app, event)
            if isempty(app.ActivePatterns)
                return;
            end
            
            group = app.ActivePatterns{app.activePatternRow}.group;
            node = app.ActivePatterns{app.activePatternRow}.node;
            ch = app.ActivePatterns{app.activePatternRow}.ch;
            
            psel = cellfun(@(x) x.group == group && x.node == node && x.ch == ch, app.Patterns);
            
            if sum(psel) == 1
                app.Patterns(psel)=[];
                
                p = cellfun(@(x) x.group == group, app.Patterns);
                if any(p)
                    app.setActivePatterns(p)
                    app.updateActivePattern(1);
                else
                    app.clearActivePatterns();
                end
            elseif sum(p) == 0
                warning('could not locate pattern')
            else
                warning('located multiple patterns')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Pattern Editor';
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.FIleMenu = uimenu(app.UIFigure);
            app.FIleMenu.Text = 'FIle';
            app.OpenMenu = uimenu(app.FIleMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FIleMenu);
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.FIleMenu);
            app.SaveAsMenu.Text = 'Save As';
            app.GroupsMenu = uimenu(app.UIFigure);
            app.GroupsMenu.MenuSelectedFcn = createCallbackFcn(app, @GroupsMenuSelected, true);
            app.GroupsMenu.Text = 'Groups';
            app.ChannelsMenu = uimenu(app.UIFigure);
            app.ChannelsMenu.Text = 'Channels';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.Visible = 'off';
            app.EditModeSwitchLabel = uilabel(app.UIFigure);
            app.EditModeSwitchLabel.Text = 'Edit Mode';
            app.EditModeSwitch = uiswitch(app.UIFigure, 'slider');
            app.EditModeSwitch.Items = {'Pattern Values', 'Breakpoints'};
            app.EditModeSwitch.ValueChangedFcn = createCallbackFcn(app, @EditModeSwitchValueChanged, true);
            app.EditModeSwitch.Value = 'Pattern Values';
            app.ViewModeSwitch = uiswitch(app.UIFigure, 'slider');
            app.ViewModeSwitch.Items = {'Amplitude', 'Pulse Width'};
            app.ViewModeSwitch.Enable = 'off';
            app.ViewModeSwitch.Visible = 'off';
            app.ViewModeSwitch.Value = 'Pulse Width';
            app.StimulationPanel = uipanel(app.UIFigure);
            app.StimulationPanel.Title = 'Stimulation';
            app.StimONButton = uibutton(app.StimulationPanel, 'push');
            app.StimONButton.Text = 'Stim ON';
            app.StimOFFButton = uibutton(app.StimulationPanel, 'push');
            app.StimOFFButton.Text = 'Stim OFF';
            app.StimModeSwitchLabel = uilabel(app.StimulationPanel);
            app.StimModeSwitchLabel.Text = 'Stim Mode';
            app.StimModeSwitch = uiswitch(app.StimulationPanel, 'slider');
            app.StimModeSwitch.Items = {'Downloaded', 'Displayed'};
            app.StimModeSwitch.Value = 'Downloaded';
            app.HighBandwidthUsePDOsCheckBox = uicheckbox(app.StimulationPanel);
            app.HighBandwidthUsePDOsCheckBox.Text = {'High Bandwidth'; '(Use PDOs)'};
            app.CommandValueEditFieldLabel = uilabel(app.StimulationPanel);
            app.CommandValueEditFieldLabel.Text = 'Command Value';
            app.CommandValueEditField = uieditfield(app.StimulationPanel, 'numeric');
            app.CommandValueEditField.RoundFractionalValues = 'on';
            app.CommandValueEditField.ValueChangedFcn = createCallbackFcn(app, @CommandValueEditFieldValueChanged, true);
            app.PatternGroupDropDownLabel = uilabel(app.UIFigure);
            app.PatternGroupDropDownLabel.Text = 'Pattern Group';
            app.PatternGroupDropDown = uidropdown(app.UIFigure);
            app.PatternGroupDropDown.Items = {'-'};
            app.PatternGroupDropDown.ValueChangedFcn = createCallbackFcn(app, @PatternGroupDropDownValueChanged, true);
            app.PatternGroupDropDown.Value = '-';
            app.ModuleDropDownLabel = uilabel(app.UIFigure);
            app.ModuleDropDownLabel.Text = 'Module';
            app.ModuleDropDown = uidropdown(app.UIFigure);
            app.ModuleDropDown.Items = {'-'};
            app.ModuleDropDown.ValueChangedFcn = createCallbackFcn(app, @ModuleDropDownValueChanged, true);
            app.ModuleDropDown.Value = '-';
            app.SystemPatternsPanel = uipanel(app.UIFigure);
            app.SystemPatternsPanel.Title = 'System Patterns';
            app.WritetoModulesButton = uibutton(app.SystemPatternsPanel, 'push');
            app.WritetoModulesButton.Text = 'Write to Module(s)';
            app.ReadfromModulesButton = uibutton(app.SystemPatternsPanel, 'push');
            app.ReadfromModulesButton.Text = 'Read from Module(s)';
            app.CompareModulesButton = uibutton(app.SystemPatternsPanel, 'push');
            app.CompareModulesButton.Text = 'Compare Module(s)';
            app.CurrentModuleOnlyCheckBox = uicheckbox(app.SystemPatternsPanel);
            app.CurrentModuleOnlyCheckBox.Text = 'Current Module Only';
            app.Panel = uipanel(app.UIFigure);
            app.PatternTable = uitable(app.Panel);
            app.PatternTable.ColumnName = {''};
            app.PatternTable.RowName = {};
            app.PatternTable.ColumnEditable = [false false false false false false true true false false false];
            app.PatternTable.CellEditCallback = createCallbackFcn(app, @PatternTableCellEdit, true);
            app.ColorTable = uitable(app.Panel);
            app.ColorTable.ColumnName = {''};
            app.ColorTable.RowName = {};
            app.PatternTableHeadings = uitable(app.Panel);
            app.PatternTableHeadings.ColumnName = {'Pattern Group'; 'Node'; 'Ch'; 'Cmd'; 'Min'; 'Max'; 'Show'; 'Mute'; 'PW'; 'Amp'; 'Muscle'; 'Select'};
            app.PatternTableHeadings.RowName = {};
            app.ButtonUp = uibutton(app.UIFigure, 'push');
            app.ButtonUp.ButtonPushedFcn = createCallbackFcn(app, @ButtonUpPushed, true);
            app.ButtonUp.Text = '▲';
            app.ButtonDown = uibutton(app.UIFigure, 'push');
            app.ButtonDown.ButtonPushedFcn = createCallbackFcn(app, @ButtonDownPushed, true);
            app.ButtonDown.Text = '▼';
            app.ButtonTop = uibutton(app.UIFigure, 'push');
            app.ButtonTop.ButtonPushedFcn = createCallbackFcn(app, @ButtonTopPushed, true);
            app.ButtonTop.Text = {'▬'; '▲'};
            app.ButtonBottom = uibutton(app.UIFigure, 'push');
            app.ButtonBottom.ButtonPushedFcn = createCallbackFcn(app, @ButtonBottomPushed, true);
            app.ButtonBottom.Text = {'▼'; '▬'};
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, 'Command Level')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes2.Visible = 'off';
            app.PatternValuesPanel = uipanel(app.UIFigure);
            app.PatternValuesPanel.Title = 'Pattern Values';
            app.PatternValuesTable = uitable(app.PatternValuesPanel);
            app.PatternValuesTable.ColumnName = {'cmd'; 'PW'; 'Amp'};
            app.PatternValuesTable.ColumnSortable = false;
            app.PatternValuesTable.ColumnEditable = [true true true];
            app.AllShowCheckBox = uicheckbox(app.UIFigure);
            app.AllShowCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllShowCheckBoxValueChanged, true);
            app.AllShowCheckBox.Text = '';
            app.AllMuteCheckBox = uicheckbox(app.UIFigure);
            app.AllMuteCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllMuteCheckBoxValueChanged, true);
            app.AllMuteCheckBox.Text = '';
            app.AddChannelPatternButton = uibutton(app.UIFigure, 'push');
            app.AddChannelPatternButton.ButtonPushedFcn = createCallbackFcn(app, @AddChannelPatternButtonPushed, true);
            app.AddChannelPatternButton.Text = '+';
            app.RemoveChannelPatternButton = uibutton(app.UIFigure, 'push');
            app.RemoveChannelPatternButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveChannelPatternButtonPushed, true);
            app.RemoveChannelPatternButton.Text = '-';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = patternedit

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end