classdef pdoConfigRead < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        OpenMenu                       matlab.ui.container.Menu
        SaveMenu                       matlab.ui.container.Menu
        SaveAsMenu                     matlab.ui.container.Menu
        UserIDEditField                matlab.ui.control.EditField
        BPNodesLabel                   matlab.ui.control.Label
        NodeListBP                     matlab.ui.control.TextArea
        StimNodesLabel                 matlab.ui.control.Label
        NodeListStim                   matlab.ui.control.TextArea
        NodeSelect                     matlab.ui.control.DropDown
        NodeLabel                      matlab.ui.control.Label
        ReadingPanel                   matlab.ui.container.Panel
        test2                          matlab.ui.control.NumericEditField
        nodeLabel                      matlab.ui.control.Label
        test1                          matlab.ui.control.NumericEditField
        indLabel                       matlab.ui.control.Label
        ReadingLamp                    matlab.ui.control.Lamp
        ReadingLampLabel               matlab.ui.control.Label
        Messages                       matlab.ui.control.TextArea
        MessagesTextAreaLabel          matlab.ui.control.Label
        ResetTPDOTabletoDefaultButton  matlab.ui.control.Button
        PMversionPanel                 matlab.ui.container.Panel
        PMverEditField                 matlab.ui.control.NumericEditField
        PMverEditFieldLabel            matlab.ui.control.Label
        PMVersionButton                matlab.ui.control.Button
        TpdosEditField                 matlab.ui.control.NumericEditField
        TpdosEditFieldLabel            matlab.ui.control.Label
        ReadRemoteModulesButton        matlab.ui.control.Button
        PMODSaveButton                 matlab.ui.control.Button
        ReadPMTPDOsButton              matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        RPDOPMTab                      matlab.ui.container.Tab
        PMRPDOLabel                    matlab.ui.control.Label
        x1400SettingsLabel             matlab.ui.control.Label
        RPDOsettingsTable              matlab.ui.control.Table
        x1600MappingLabel              matlab.ui.control.Label
        RPDOmapTable                   matlab.ui.control.Table
        TPDOPMTab                      matlab.ui.container.Tab
        PMTPDOLabel                    matlab.ui.control.Label
        x1800SettingsLabel             matlab.ui.control.Label
        TPDOsettingsTable              matlab.ui.control.Table
        x1A00MappingLabel              matlab.ui.control.Label
        TPDOmapTable                   matlab.ui.control.Table
        StimTab                        matlab.ui.container.Tab
        FilteredAccelerometersSwitch   matlab.ui.control.Switch
        FilteredAccelerometersLabel    matlab.ui.control.Label
        RpdoTestYPGPanel               matlab.ui.container.Panel
        PGRpdoID                       matlab.ui.control.Table
        PGRpdomapping                  matlab.ui.control.Table
        TpdoTempAccPanel               matlab.ui.container.Panel
        PGTpdomapping                  matlab.ui.control.Table
        BPTab                          matlab.ui.container.Tab
        TpdoTempAccPanelBP             matlab.ui.container.Panel
        BPTpdomapping                  matlab.ui.control.Table
        RMPDOsTab                      matlab.ui.container.Tab
        x1600Label                     matlab.ui.control.Label
        RPDO_RMmap                     matlab.ui.control.Table
        RPDO_RMcfg                     matlab.ui.control.Table
        x1400Label                     matlab.ui.control.Label
        MappingsLabel                  matlab.ui.control.Label
        SettingsLabel                  matlab.ui.control.Label
        ReceiveLabel                   matlab.ui.control.Label
        RMTPDOsTab                     matlab.ui.container.Tab
        MappingsLabel_2                matlab.ui.control.Label
        SettingsLabel_2                matlab.ui.control.Label
        x1A00Label                     matlab.ui.control.Label
        TPDO_RMmap                     matlab.ui.control.Table
        TPDO_RMcfg                     matlab.ui.control.Table
        x1800Label                     matlab.ui.control.Label
        TransmitLabel                  matlab.ui.control.Label
        ReadPMRPDOButton               matlab.ui.control.Button
        ResetRPDOTabletoDefaultButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        nnp = [];
        RPDO = []; %create RPDO structure
        TPDO = []; %create TPDO structure
        myData=[]; %structure to read in from mat file
       
        PGnodelist=[];
        rmNodeList=[];
        BPnodelist=[];
        RMPDOs=struct('RPDOcfg',{[]},'RPDOmap',{[]},'TPDOcfg',{[]},'TPDOmap',{[]});  % structure for remote modules data
        saveFile=[];
        callingFigure=[]; %handle to calling figure
        defaultRPDO=[];
        defaultTPDO=[];
        styleYellowBold=uistyle('BackgroundColor',[1,1,.8],'FontWeight','bold') % light yellow bold
        % Code for auto file open
         userFolders=struct;  % userfiles, code, user, config, data, log
         defaultSuffix='_PDO_Config.mat';
         defaultName='P00_PDO_Config.mat';
    end
    
    properties (Access = private)

    end
    
    methods (Access = private)
        
        function defaultRPDOpmSettings(app)
            
            app.RPDO.id = uint32(hex2dec({'00000689','00000189','00000181','00000182','00000183','00000184','00000185','00000186'})');
            app.RPDO.type = uint8(255); %process immediately when data received
            % RPDO.type = uint8(0); %process on next SYNC
            app.RPDO.inhibit = uint16(0); %not relevant for RPDO
            app.RPDO.compat = uint8(0); %script pointer for script that runs when PDO is received
            app.RPDO.timer = uint16(0); %not relevant for RPDO
            app.RPDO.settings=cell(5,8);
           
            for i=1:8
                app.RPDO.settings(1,i)=cellstr(dec2hex(app.RPDO.id(i),8));
                app.RPDO.settings(2,i)=cellstr(dec2hex(app.RPDO.type,2));
                app.RPDO.settings(3,i)=cellstr(dec2hex(app.RPDO.inhibit,4));
                app.RPDO.settings(4,i)=cellstr(dec2hex(app.RPDO.compat,2));
                app.RPDO.settings(5,i)=cellstr(dec2hex(app.RPDO.timer,4));
            end
            app.RPDOsettingsTable.Data=app.RPDO.settings;
            app.defaultRPDO=app.RPDO;
            removeStyle(app.RPDOsettingsTable);
        end
        
        function defaultRPDOpmMapping(app)
                   
           app.RPDOmapTable.Data=...
               {'1F53A208','1F53C008','1F53A808','1F53AC08','1F53B008','1F53B408','1F53B808','1F53BC08';...
                 '1F532E08','1F53C108','1F53A908','1F53AD08','1F53B108','1F53B508','1F53B908','1F53BD08';...
%                 '1F532E08','1F53C108','1F53A808','1F53AD08','1F53B108','1F53B408','1F53B908','1F53BD08';... %purposely change for testing
                '1F53A408','1F53C208','1F53AA08','1F53AE08','1F53B208','1F53B608','1F53BA08','1F53BE08';...
                '1F53A508','1F53C308','1F53AB08','1F53AF08','1F53B308','1F53B708','1F53BB08','1F53BF08';...
                '1F53A308','1F53E208','1F53D608','1F53D808','1F53DA08','1F53DC08','1F53DE08','1F53E008';...
                '1F532F08','1F53E308','1F53D708','1F53D908','1F53DB08','1F53DD08','1F53DF08','1F53E108';...
                '1F53A608','1F53CA08','1F53C408','1F53C508','1F53C608','1F53C708','1F53C808','1F53C908';...
                '1F53A708','1F53D108','1F53CB08','1F53CC08','1F53CD08','1F53CE08','1F53CF08','1F53D008'};
            app.RPDO.mapping = cell(8,1);
             
            for i=1:8
                app.RPDO.mapping{i}=uint32(hex2dec(app.RPDOmapTable.Data(:,i)))'; %Convert one column at a time
            end
            app.defaultRPDO=app.RPDO; 
            app.defaultRPDO.mapping=app.RPDOmapTable.Data; 
            removeStyle(app.RPDOmapTable);
        end
        
    
        function defaultTPDOpmSettings(app)
            app.TPDO.id = uint32(hex2dec({'00000187','00000287','00000387','00000487','000001C7','000002C7','80000000','80000000'})');
            %     app.TPDO.type = uint8(1); %sent with every sync, regardless if data has changed
            % app.TPDO.type = uint8(0);  %sent with every sync, only if data has changed
            app.TPDO.type = uint8(253); %disabled
            app.TPDO.inhibit = uint16(0);  %not relevant for synchronous
            app.TPDO.compat = uint8(0);  %not relevant for app.TPDO
            app.TPDO.timer = uint16(0); %not relevant for synchronous    
            for i=1:6
                app.TPDO.settings(1,i)=cellstr(dec2hex(app.TPDO.id(i),8));
                app.TPDO.settings(2,i)=cellstr(dec2hex(app.TPDO.type,2));
                app.TPDO.settings(3,i)=cellstr(dec2hex(app.TPDO.inhibit,4));
                app.TPDO.settings(4,i)=cellstr(dec2hex(app.TPDO.compat,2));
                app.TPDO.settings(5,i)=cellstr(dec2hex(app.TPDO.timer,4));
            end  
             app.TPDOsettingsTable.Data=app.TPDO.settings;
             app.defaultTPDO=app.TPDO;
            app.defaultTPDO.mapping=app.TPDOmapTable.Data; % may not be needed here because mapping follows
            removeStyle(app.TPDOsettingsTable);
        end
        
        function defaultTPDOpmMapping(app)
            app.TPDOmapTable.Data=...
                {'30210108','30210908','30211108','30211908','30212108','30212908','00000000','00000000';...
                '30210208','30210A08','30211208','30211A08','30212208','30212A08','00000000','00000000';...
                '30210308','30210B08','30211308','30211B08','30212308','30212B08','00000000','00000000';...
                '30210408','30210C08','30211408','30211C08','30212408','30212C08','00000000','00000000';...
                '30210508','30210D08','30211508','30211D08','30212508','30212D08','00000000','00000000';...
                '30210608','30210E08','30211608','30211E08','30212608','30212E08','00000000','00000000';...
                '30210708','30210F08','30211708','30211F08','30212708','30212F08','00000000','00000000';...
                '30210808','30211008','30211808','30212008','30212808','30213008','00000000','00000000'};
            
            app.TPDO.mapping = cell(8,1);
            
            for i=1:8
                app.TPDO.mapping{i}=uint32(hex2dec(app.TPDOmapTable.Data(:,i)))'; %Convert one column at a time
            end
            app.defaultTPDO=app.TPDO;
            app.defaultTPDO.mapping=app.TPDOmapTable.Data; % want to compare with table later
            removeStyle(app.TPDOmapTable);
            %             app.TPDO.mapping{1} = uint32(hex2dec({'30210108','30210208','30210308','30210408','30210508','30210608','30210708','30210808'})');
            %             app.TPDO.mapping{2} = uint32(hex2dec({'30210908','30210a08','30210b08','30210c08','30210d08','30210e08','30210f08','30211008'})');
            %             app.TPDO.mapping{3} = uint32(hex2dec({'30211108','30211208','30211308','30211408','30211508','30211608','30211708','30211808'})');
            %             app.TPDO.mapping{4} = uint32(hex2dec({'30211908','30211a08','30211b08','30211c08','30211d08','30211e08','30211f08','30212008'})');
            %             app.TPDO.mapping{5} = uint32(hex2dec({'30212108','30212208','30212308','30212408','30212508','30212608','30212708','30212808'})');
            %             app.TPDO.mapping{6} = uint32(hex2dec({'30212908','30212a08','30212b08','30212c08','30212d08','30212e08','30212f08','30213008'})');
            %             app.TPDO.mapping{7} = uint32(hex2dec({'00000000','00000000','00000000','00000000','00000000','00000000','00000000','00000000'})');
            %             app.TPDO.mapping{8} = uint32(hex2dec({'00000000','00000000','00000000','00000000','00000000','00000000','00000000','00000000'})');
            %
        end
        
        function defaultTPDOpgmapping(app)
            if app.FilteredAccelerometersSwitch.Value == "On"
                app.PGTpdomapping.Data={'20110220','20030110','30000208','30000308','00000000','00000000','00000000','00000000'}';
            else
                app.PGTpdomapping.Data={'20110120','20030110','30000208','30000308','00000000','00000000','00000000','00000000'}';
            end
        end
        
        function defaultRPDOpg(app)
            app.PGRpdomapping.Data={'32120110','32120210','32120310','32120410','00000000','00000000','00000000','00000000'}';
            app.PGRpdoID.Data={'00000187','00000287','00000387','00000487','000001C7','000002C7','00000407','00000507'}';
        end
    end
    
    methods (Access = public)
        
        function ResponseMessage(app,good,message)
            if good
                dmessage=message;
                tbc=[1,1,1];
            else
                dmessage=app.nnp.lastError;
                tbc=[1,1,.7];
            end
            app.TopMessage(dmessage);
            app.Messages.BackgroundColor=tbc;
        
        end
        
        function getPMversion(app)
            resp=double(app.nnp.read(7,'1018',3,'uint32')); % read revision number to test communications
            if ~isempty(resp)
               app.PMverEditField.Value=resp; 
               if resp>=405
                   app.TpdosEditField.Value=6;
               else
                   app.TpdosEditField.Value=8;
               end
            end
            app.ResponseMessage(~isempty(resp),sprintf('PM version %d',resp));
        end
        
        function defaultTPDObpmapping(app)
                app.BPTpdomapping.Data={'20110220','20030110','30000208','30000308','00000000','00000000','00000000','00000000'}';
        end
        
        function TopMessage(app,message)
            app.Messages.Value=vertcat(message, string(app.Messages.Value)); % put new message on top  
        end
        
        function ClearMessages(app)
               app.Messages.Value=''; % clear messages         
        end
        
        function pdosettings = ODPDOsettings(app,firstindex,node,n)
            
            %  The plan here is to create a single function to fill the cell array and reuse it in other locations
            %  The index to read will be first index plus an increment. Since Matlab indexes by 1, it will be fi+i-1
            pdosettings={[]};
            % indexOD=[]; %plan to for firstindex to be hex, ie '1400'
            %  Since visually, these make no sense as doubles, need to convert to hex
            for i=1:n
                indexOD=dec2hex((hex2dec(firstindex)+i-1),4); %since hex math is not allowed, need to convert twice
                pdosettings{1,i}=dec2hex(app.nnp.read(node,indexOD,1,'uint32'),8);
                pdosettings{2,i}=dec2hex(app.nnp.read(node,indexOD,2,'uint8'),2);
                pdosettings{3,i}=dec2hex(app.nnp.read(node,indexOD,3,'uint16'),4);
                pdosettings{4,i}=dec2hex(app.nnp.read(node,indexOD,4,'uint8'),2);
                pdosettings{5,i}=dec2hex(app.nnp.read(node,indexOD,5,'uint16'),4);
                pause(.3);
            end
        end
        
        
        function pdomaps = ODPDOmaps(app,firstindex,node,n)
            pdomaps={[]};
            %  indexOD=firstindex;  %plan to for firstindex to be hex, ie '1600'
            %  Since visually, these make no sense as doubles, need to convert to hex
            for i=1:n
                indexOD=dec2hex((hex2dec(firstindex)+i-1),4); %since hex math is not allowed, need to convert twice
                for j=1:8
                    pdomaps{j,i}=dec2hex(app.nnp.read(node,indexOD,j,'uint32'),8);
                end
                pause(.3);
            end
        end
        
        function RMNodeList = getRMNodeList(app)
            % Here I am going to read the network status, and from that,
            % determine which nodes are connected.
            RMNodeList=[]; % start with empty list
            app.nnp.enterWaiting();  % need to make sure in waiting mode
            app.nnp.networkOn();
            pause(1); % wait for network to start
            resp=app.nnp.nmt(7,'10'); % read status for nodes 1:15
            % The node states should be in elements 2:16
            if length(resp)==29 % read correctly
                for i=[1:6,9] % don't need to read the otheres
                    if ~isempty(resp(i+1))
                        if resp(i+1)==1 % should be in waiting mode
                            RMNodeList=[RMNodeList i];
                        end
                    end
                end
            end
        end
        
        function OpenFileByName(app,filename,filepath)
             if filename
                 ld = load(fullfile(filepath, filename));               
            else
                return;
            end
            fields = {'TPDOsettingsTableData', 'PGRpdoIDData', 'PGRpdomappingData', 'PGTpdomappingData', ...
                'RPDO', 'RPDOmapTableData', 'RPDOsettingsTableData', 'TPDO', 'TPDOmapTableData', 'NodeListStimValue'};
            iMissing = find(~isfield(ld, fields));
            
            str = [];
            for i=1:length(iMissing)
                str = [str ', ' fields{iMissing(i)}];
            end
            if ~isempty(str)
                warndlg(['Invalid NNP System File.  Does not contain variable(s): ' str '.'], 'Open File')
                return;
            end
            %               If the file is invalid, we don't want to update anything, including the file name
            app.RPDO=ld.RPDO;
            app.RPDOsettingsTable.Data=ld.RPDOsettingsTableData;
            app.RPDOmapTable.Data=ld.RPDOmapTableData;
            app.TPDO=ld.TPDO;
            app.TPDOsettingsTable.Data=ld.TPDOsettingsTableData;
            app.TPDOmapTable.Data=ld.TPDOmapTableData;
            app.NodeListStim.Value=ld.NodeListStimValue;
            app.PGRpdoID.Data=ld.PGRpdoIDData;
            app.PGRpdomapping.Data=ld.PGRpdomappingData;
            app.PGTpdomapping.Data=ld.PGTpdomappingData;
            figure(app.UIFigure); %return focus
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin<2
                NNP=[];
            else
                NNP=varargin{1};
            end
            if isempty(NNP)
                app.nnp = NNPAPI();
                if isempty(app.nnp.port)
                    % msgbox('No port chosen for NNP Access Point')
                    uialert(app.UIFigure,'No port chosen for NNP Access Point','No NNP AP')
                    delete(app.nnp)
                    return
                end
            else
                app.nnp = NNP;
            end
            if nargin>2
                app.callingFigure=varargin{2};
            end
           if nargin>3  % third is userFolders
                app.userFolders=varargin{3};  % userfiles, code, user, config, data, log
                app.UserIDEditField.Value=app.userFolders.code;
            end
            app.getPMversion();
            app.RPDOsettingsTable.RowName={'ID','type','inhibit','compat','timer'};
            app.TPDOsettingsTable.RowName={'ID','type','inhibit','compat','timer'};
            app.defaultRPDOpmSettings();
            app.defaultRPDOpmMapping();
            app.defaultTPDOpmSettings();
            app.defaultTPDOpmMapping();
            app.defaultTPDOpgmapping();
            app.defaultTPDObpmapping();
            app.defaultRPDOpg();
           app.TopMessage('pausing for testing...'); 
           if isfield(app.userFolders,'config')&& ~isempty(app.userFolders.config)
               filename=[app.userFolders.code,app.defaultSuffix];
               app.defaultName=filename; % this is reduntant, could combine above
               filepath=app.userFolders.config;
               fullName=fullfile(filepath,filename);
               if exist(fullName,'file')==2  % make sure file exists before trying to open
                   app.OpenFileByName(filename,filepath);
               end
           end
        end

        % Button pushed function: ResetRPDOTabletoDefaultButton
        function ResetRPDOTabletoDefaultButtonPushed(app, event)
            app.defaultRPDOpmSettings();
            app.defaultRPDOpmMapping();
%             app.TopMessage('pausing for testing...');
        end

        % Button pushed function: ReadPMRPDOButton
        function ReadPMRPDOButtonPushed(app, event)
            app.nnp.enterWaiting();  % need to make sure in waiting mode before save
            % % Write to OD 140i.n RPDO Settings
            % result shoud return 0 if successful.  Otherwise will return []
%             if ~isequal(result, 0)
%                 app.TopMessage('Error Writing ...');
%                 return;
%             else
%                 app.TopMessage('... to PM');
%             end 
            % Keep track of whether an error occured
            ReadError=0; %start with no write errors
            app.TopMessage('Reading RPDOs...');

            k=7; % k=node number
            p=8; % p is number of pdos in that node

            % 1400-1403 receive PDO configuration, 10 bytes per index
            app.RPDO.settings=app.ODPDOsettings('1400',k,p); %for the first element I want to display result
            % 1600-1603 receive PDO mapping, 32 bytes
            app.RPDO.mapping=app.ODPDOmaps('1600',k,p);
            app.RPDOsettingsTable.Data=app.RPDO.settings;
            app.RPDOmapTable.Data=app.RPDO.mapping;
            
            % check PM version number and read either 6 or 8 for p
%             p=6; % both have at least 6, so start there
%             % 1800-1803 TPDO configuration, 10 bytes per index
%             app.TPDO.settings = app.ODPDOsettings('1800',k,p);
%             % 1A00-1A03 TPDO mapping, 32 bytes
%             app.TPDO.mapping =app.ODPDOmaps('1A00',k,p);
%             
%             app.TPDOsettingsTable.Data=app.TPDO.settings;
%             app.TPDOmapTable.Data=app.TPDO.mapping;
            
%             for i=1:8
%                 %     for i=1:1
%                 app.RPDO.mapping{i}=uint32(hex2dec(app.RPDOmapTable.Data(:,i)))'; %Convert one column at a time
% 
%                 result=app.nnp.write(7, ['160' num2str(i-1)], 1, app.RPDO.mapping{i}, 'uint32',8);
%                 if result~=0
%                     writeError=writeError+1;
%                 end
%                result=app.nnp.write(7, ['140' num2str(i-1)], 1, app.RPDO.id(i), 'uint32');
%                if result~=0
%                     writeError=writeError+1;
%                 end
%                 result=app.nnp.write(7, ['140' num2str(i-1)], 2, app.RPDO.type, 'uint8');
%                 if result~=0
%                     writeError=writeError+1;
%                 end
%                 result=app.nnp.write(7, ['140' num2str(i-1)], 3, app.RPDO.inhibit, 'uint16');
%                 if result~=0
%                     writeError=writeError+1;
%                 end
%                 result=app.nnp.write(7, ['140' num2str(i-1)], 4, app.RPDO.compat, 'uint8');
%                 if result~=0
%                     writeError=writeError+1;
%                 end
%                 result=app.nnp.write(7, ['140' num2str(i-1)], 5, app.RPDO.timer, 'uint16');
%                 if result~=0
%                     writeError=writeError+1;
%                 end
%                 pause(.25); % add this delay to allow serial port to catch up
%             end
            dmessage=sprintf('%d errors Read RPOs from PM',ReadError);
            app.TopMessage(dmessage);
            
            % compare with default
            %  mapping
            rd=app.defaultRPDO.mapping;
            r1=app.RPDO.mapping;
            rSame=strcmp(rd,r1); %logical difference
            
            for r=1:8
                for c=1:8
                    if ~rSame(r,c)
                        addStyle(app.RPDOmapTable,app.styleYellowBold,'cell',[r,c]); % added in order                    
                    end                    
                end           
            end
            % settings
            td=app.defaultRPDO.settings;
            t1=app.RPDO.settings;
            tSame=strcmp(td,t1); %logical difference
            
            for r=1:5 % 5 rows for settings
                for c=1:8 
                    if ~tSame(r,c)
                        addStyle(app.RPDOsettingsTable,app.styleYellowBold,'cell',[r,c]); % added in order                    
                    end                    
                end           
            end
        end

        % Button pushed function: ReadPMTPDOsButton
        function ReadPMTPDOsButtonPushed(app, event)
             % Keep track of whether an error occured
            ReadError=0; %start with no write errors
            app.TopMessage('Reading TPDOs...');

            k=7; % k=node number

            p=6; % both have at least 6, so start there
            % 1800-1803 TPDO configuration, 10 bytes per index
            app.TPDO.settings = app.ODPDOsettings('1800',k,p);
            % 1A00-1A03 TPDO mapping, 32 bytes
            app.TPDO.mapping =app.ODPDOmaps('1A00',k,p);
            
            app.TPDOsettingsTable.Data=app.TPDO.settings;
            app.TPDOmapTable.Data=app.TPDO.mapping;
%             app.TPDO.mapping = cell(8,1);
%             app.TPDO.mapping{1} = uint32(hex2dec({'30210108','30210208','30210308','30210408','30210508','30210608','30210708','30210808'})');
%             app.TPDO.mapping{2} = uint32(hex2dec({'30210908','30210a08','30210b08','30210c08','30210d08','30210e08','30210f08','30211008'})');
%             app.TPDO.mapping{3} = uint32(hex2dec({'30211108','30211208','30211308','30211408','30211508','30211608','30211708','30211808'})');
%             app.TPDO.mapping{4} = uint32(hex2dec({'30211908','30211a08','30211b08','30211c08','30211d08','30211e08','30211f08','30212008'})');
%             app.TPDO.mapping{5} = uint32(hex2dec({'30212108','30212208','30212308','30212408','30212508','30212608','30212708','30212808'})');
%             app.TPDO.mapping{6} = uint32(hex2dec({'30212908','30212a08','30212b08','30212c08','30212d08','30212e08','30212f08','30213008'})');
%             app.TPDO.mapping{7} = uint32(hex2dec({'00000000','00000000','00000000','00000000','00000000','00000000','00000000','00000000'})');
%             app.TPDO.mapping{8} = uint32(hex2dec({'00000000','00000000','00000000','00000000','00000000','00000000','00000000','00000000'})');
%             
%             app.TPDO.id = uint32(hex2dec({'00000187','00000287','00000387','00000487','000001C7','000002C7','80000000','80000000'})');
%             %     app.TPDO.type = uint8(1); %sent with every sync, regardless if data has changed
%             % app.TPDO.type = uint8(0);  %sent with every sync, only if data has changed
%             app.TPDO.type = uint8(253); %disabled
%             app.TPDO.inhibit = uint16(0);  %not relevant for synchronous
%             app.TPDO.compat = uint8(0);  %not relevant for app.TPDO
%             app.TPDO.timer = uint16(0); %not relevant for synchronous
%             
%             %Write to OD 180i,m PM TPDOs
%             writeError=0; %start with no write errors
%             app.TopMessage('Writing TPDOs...');
%             for i=1:app.TpdosEditField.Value % change to 6 starting with PM 405
%                 app.TPDO.mapping{i}=uint32(hex2dec(app.TPDOmapTable.Data(:,i)))'; %Convert one column at a time
%                 result=app.nnp.write(7, ['1A0' num2str(i-1)], 1, app.TPDO.mapping{i}, 'uint32',8);
%                 if result~=0
%                     writeError=writeError+1;
%                 end
%                 result=app.nnp.write(7, ['180' num2str(i-1)], 1, app.TPDO.id(i), 'uint32');
%                 if result~=0
%                     writeError=writeError+1;
%                 end
%                 result=app.nnp.write(7, ['180' num2str(i-1)], 2, app.TPDO.type, 'uint8');
%                 if result~=0
%                     writeError=writeError+1;
%                 end
%                 result=app.nnp.write(7, ['180' num2str(i-1)], 3, app.TPDO.inhibit, 'uint16');
%                 if result~=0
%                     writeError=writeError+1;
%                 end
%                 result=app.nnp.write(7, ['180' num2str(i-1)], 4, app.TPDO.compat, 'uint8');
%                 if result~=0
%                     writeError=writeError+1;
%                 end
%                 result=app.nnp.write(7, ['180' num2str(i-1)], 5, app.TPDO.timer, 'uint16');
%                 if result~=0
%                     writeError=writeError+1;
%                 end
             dmessage=sprintf('%d errors Reading TPOs from PM',ReadError);
            app.TopMessage(dmessage);
            td=app.defaultTPDO.mapping;
            t1=app.TPDO.mapping;
            tSame=strcmp(td(:,1:6),t1(:,1:6)); %logical difference only want six columns
            
            for r=1:8
                for c=1:6
                    if ~tSame(r,c)
                        addStyle(app.TPDOmapTable,app.styleYellowBold,'cell',[r,c]); % added in order                    
                    end                    
                end           
            end
            
             % settings
            td=app.defaultTPDO.settings;         
            t1=app.TPDO.settings;          
            tSame=strcmp(td(:,1:6),t1(:,1:6)); %logical difference only want six columns
            
            for r=1:5 % 5 rows for settings
                for c=1:6 % only 6 columns for Tpdos
                    if ~tSame(r,c)
                        addStyle(app.TPDOsettingsTable,app.styleYellowBold,'cell',[r,c]); % added in order                    
                    end                    
                end           
            end
        end

        % Button pushed function: PMODSaveButton
        function PMODSaveButtonPushed(app, event)
             resultsave=app.nnp.saveOD(7); %true or false
            if  resultsave
                app.TopMessage('PM OD saved');
            else
                app.TopMessage('Error saving PM OD');
            end
        end

        % Button pushed function: ReadRemoteModulesButton
        function ReadRemoteModulesButtonPushed(app, event)
            app.rmNodeList=app.getRMNodeList(); % get list of nodes
            rmNodeLength=size(app.rmNodeList,2);
            % from this list I will want to create column headings
            rmColumnNames=cell(1,rmNodeLength); % create the cell array
            for n=1:rmNodeLength
                rmColumnNames{n}=strcat('Node',num2str(app.rmNodeList(n)));
            end
            app.ReadingLamp.Enable='on'; % enable so that it shows up better
            app.ReadingLamp.Color='g'; %turn green while reading from RM
            pause(.5);
            app.RMPDOs.RPDOcfg=cell(5,rmNodeLength); % create the cell array first
            app.RMPDOs.RPDOmap=cell(8,rmNodeLength); % create the cell array first
            app.RMPDOs.TPDOcfg=cell(5,rmNodeLength); % create the cell array first
            app.RMPDOs.TPDOmap=cell(8,rmNodeLength); % create the cell array first
            for i=1:rmNodeLength
                k=app.rmNodeList(i); % where k=node number
                app.test1.Value=i;
                app.test2.Value=k;
                p=1; % p is number of pdos in that node
                % Since each return value will be a single column, can I
                % just save these as columns directly?
                temp1=ODPDOsettings(app,'1400',k,p);
                temp2=ODPDOmaps(app,'1600',k,p);
                % I am using 4 temp variables to make debuggined easier,
                % but could use just 1
                app.RMPDOs.RPDOcfg(:,i)=temp1(:,1);  % 1400-1403 receive PDO configuration, 10 bytes per index
                app.RMPDOs.RPDOmap(:,i)=temp2(:,1);  % 1600-1603 receive PDO mapping, 32 bytes

                temp3=ODPDOsettings(app,'1800',k,p); % 1800-1803 TPDO configuration, 10 bytes per index
                temp4=ODPDOmaps(app,'1A00',k,p); % 1A00-1A03 TPDO mapping, 32 bytes
                app.RMPDOs.TPDOcfg(:,i)=temp3(:,1); % 1800-1803 TPDO configuration, 10 bytes per index
                app.RMPDOs.TPDOmap(:,i)=temp4(:,1); % 1A00-1A03 TPDO mapping, 32 bytes
                % could add color change for each module
            end
            % at this point each element of RMPDOs(i) will be a column of
            % data.  In order to display this as a table, I need to
            % convert into columns.
            app.ReadingLamp.Color='y'; %turn yellow while updating display
            pause(.5);

            app.RPDO_RMcfg.Data=app.RMPDOs.RPDOcfg;  % 1400
            app.RPDO_RMmap.Data=app.RMPDOs.RPDOmap;  % 1600
            app.TPDO_RMcfg.Data=app.RMPDOs.TPDOcfg;  % 1800
            app.TPDO_RMmap.Data=app.RMPDOs.TPDOmap;  % 1A00
            % add column names here
            app.RPDO_RMcfg.ColumnName = rmColumnNames;
            app.RPDO_RMmap.ColumnName = rmColumnNames;
            app.TPDO_RMcfg.ColumnName = rmColumnNames;
            app.TPDO_RMmap.ColumnName = rmColumnNames;
            
            app.ReadingLamp.Color='k'; % back to green then off while starting network
            app.ReadingLamp.Enable='off'; %turn lamp off at end
            app.nnp.worOn(20);
            app.test1.Value=0; %reset to zero
            app.test2.Value=0;


%             %Write TPDOs to send back Accelerometers, temperature and current
%             
%             app.PGnodelist=str2num(cell2mat(app.NodeListStim.Value)); %at some point I want to read this from the calling function.  Assume 1-6 for now
%             %Since I am writing these in different commands, I can reuse teh TPDO structure
%             app.TPDO.mapping = uint32(hex2dec(app.PGTpdomapping.Data));
%             % 0x20 = 32 bits= 4 bytes for Acc data
%             % if revision number is <167, then need first index to be uint32(hex2dec('2011101200')) which is unfiltered
%             % 0x10 = 16 bits for temperature data
%             app.TPDO.id = uint32(hex2dec('00000180'));
%             % TPDO.type = uint8(1); %sent with every sync, regardless if data has changed
%             % TPDO.type = uint8(0);  %sent with every sync, only if data has changed
%             app.TPDO.type = uint8(253);  %disabled - will enable as needed
%             app.TPDO.inhibit = uint16(0); %not relevant for synchronous
%             app.TPDO.compat = uint8(0); %not relevant for RMs
%             app.TPDO.timer = uint16(0); %not relevant for synchronous
%             
%             app.TopMessage('Writing RM TPDOs...');
%            
%             for i=1:length(app.PGnodelist)
%                 currentNode=app.PGnodelist(i); % for error messages
%                 % get module revision number
%                 resp=double(app.nnp.read(currentNode,'1018',3,'uint32')); % read revision number of node
%                 if ~isempty(resp)
%                     pgversion=resp;
%                     vermessage=sprintf('PG firmware version %d',pgversion);
%                     app.TopMessage(vermessage);
%                     if pgversion<167 % no filtering before 167
%                         % same as turning filtering switch off, but does this automatically on for each node
%                         app.TPDO.mapping(1)=uint32(hex2dec('20110120')); % force correct mapping for this revision of firmware
%                    %     app.PGTpdomapping.Data{1}='20110120'; % Force correct default value into table
%                     end
%                                         
%                     writeError=0; %start with no write errors
%                     %BlockWrite isnt implemented at CAN level.
%                     for j =1:8
%                         result=app.nnp.write(app.PGnodelist(i), '1A00', j, app.TPDO.mapping(j), 'uint32');
%                         if result~=0
%                             writeError=writeError+1;
%                         end
%                     end
%                     result=app.nnp.write(app.PGnodelist(i), '1800', 1, app.TPDO.id+app.PGnodelist(i), 'uint32');
%                     if result~=0
%                         writeError=writeError+1;
%                     end
%                     result=app.nnp.write(app.PGnodelist(i), '1800', 2, app.TPDO.type, 'uint8');
%                     if result~=0
%                         writeError=writeError+1;
%                     end
%                     result=app.nnp.write(app.PGnodelist(i), '1800', 3, app.TPDO.inhibit, 'uint16');
%                     if result~=0
%                         writeError=writeError+1;
%                     end
%                     result=app.nnp.write(app.PGnodelist(i), '1800', 4, app.TPDO.compat, 'uint8');
%                     if result~=0
%                         writeError=writeError+1;
%                     end
%                     result=app.nnp.write(app.PGnodelist(i), '1800', 5, app.TPDO.timer, 'uint16');
%                     if result~=0
%                         writeError=writeError+1;
%                     end
%                     dmessage=sprintf('%d errors writing TPOs to Node %d',writeError,currentNode);
%                     app.TopMessage(dmessage);
%                     pause(.2);
%                     % save to OD
%                     % should only save if there are no errors
%                     if writeError==0
%                         resultsave=app.nnp.saveOD(app.PGnodelist(i)); % save to OD
%                         if  resultsave
%                             dmessage=sprintf('Saved OD node %d',currentNode);
%                         else
%                             dmessage=sprintf('Error saving OD node %d',currentNode);
%                         end
%                         app.TopMessage(dmessage);
%                         pause(.25);
%                     end
%                 else
%                     vermessage=sprintf('Could not read PG %d version number',i);
%                     app.TopMessage(vermessage);
%                 end

            
        end

        % Button pushed function: ResetTPDOTabletoDefaultButton
        function ResetTPDOTabletoDefaultButtonPushed(app, event)
            app.defaultTPDOpmSettings();
            app.defaultTPDOpmMapping();
%             app.TopMessage('pausing for testing...');
        end

        % Button pushed function: PMVersionButton
        function PMVersionButtonPushed(app, event)
            app.getPMversion();
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            resp = questdlg('You may have unsaved changes.  Do you want to continue with a new file?');
            if ~isequal(resp, 'Yes')
                return;
            end
            if isfield(app.userFolders,'config')&& ~isempty(app.userFolders.config)
                    fname=[app.userFolders.code,app.defaultSuffix];
                    defName=fullfile(app.userFolders.config,fname); % name with path                  
            else
                defName=app.defaultName; % name without path
            end
			[filename, filepath] = uigetfile('.mat', 'Select PDO Configuration File',defName); % Exercise parameter file

            if filename
                app.OpenFileByName(filename,filepath);
            end

            % open file and load into tables and variable
            %{
            if filename
                ld = load([filepath filename]);
                TempFile = [filepath filename];  % save filename as temporary until the file is checked
                
            else
                return;
            end
            fields = {'TPDOsettingsTableData', 'PGRpdoIDData', 'PGRpdomappingData', 'PGTpdomappingData', ...
                'RPDO', 'RPDOmapTableData', 'RPDOsettingsTableData', 'TPDO', 'TPDOmapTableData', 'NodeListStimValue'};
            iMissing = find(~isfield(ld, fields));
            
            str = [];
            for i=1:length(iMissing)
                str = [str ', ' fields{iMissing(i)}];
            end
            if ~isempty(str)
                warndlg(['Invalid NNP System File.  Does not contain variable(s): ' str '.'], 'Open File')
                return;
            end
            %               If the file is invalid, we don't want to update anything, including the file name
            app.RPDO=ld.RPDO;
            app.RPDOsettingsTable.Data=ld.RPDOsettingsTableData;
            app.RPDOmapTable.Data=ld.RPDOmapTableData;
            app.TPDO=ld.TPDO;
            app.TPDOsettingsTable.Data=ld.TPDOsettingsTableData;
            app.TPDOmapTable.Data=ld.TPDOmapTableData;
            app.NodeListStim.Value=ld.NodeListStimValue;
            app.PGRpdoID.Data=ld.PGRpdoIDData;
            app.PGRpdomapping.Data=ld.PGRpdomappingData;
            app.PGTpdomapping.Data=ld.PGTpdomappingData;
            %}
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            if isempty(app.saveFile)
                app.SaveAsMenuSelected([])
            else
                RPDO=app.RPDO;
                RPDOsettingsTableData=app.RPDOsettingsTable.Data;
                RPDOmapTableData =app.RPDOmapTable.Data;
                TPDO=app.TPDO;
                TPDOsettingsTableData=app.TPDOsettingsTable.Data;
                TPDOmapTableData=app.TPDOmapTable.Data;
                NodeListStimValue=app.NodeListStim.Value;
                PGRpdoIDData=app.PGRpdoID.Data;
                PGRpdomappingData=app.PGRpdomapping.Data;
                PGTpdomappingData=app.PGTpdomapping.Data;
                BPTpdomappingData=app.BPTpdomapping.Data;
                save(app.saveFile, 'TPDOsettingsTableData', 'PGRpdoIDData', 'PGRpdomappingData', 'PGTpdomappingData', 'BPTpdomappingData', ...
                'RPDO', 'RPDOmapTableData', 'RPDOsettingsTableData', 'TPDO', 'TPDOmapTableData', 'NodeListStimValue');
            end            
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
            
            defName=fullfile(app.userFolders.config,app.defaultName);
            [filename, filepath] = uiputfile('.mat', 'Save PDO Configuration File As...',defName);
            if filename
                app.saveFile = fullfile(filepath, filename);
                app.SaveMenuSelected([]);
            end            
        end

        % Value changed function: FilteredAccelerometersSwitch
        function FilteredAccelerometersSwitchValueChanged(app, event)
            app.defaultTPDOpgmapping(); % the function uses the value from the switch
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ishandle(app.callingFigure) % check that is is valid
                figure(app.callingFigure); % bring to front
            end
            delete(app)
            
        end

        % Value changed function: NodeSelect
        function NodeSelectValueChanged(app, event)
            i = app.NodeSelect.Value;
            app.RPDO_RMcfg.Data=app.RMPDOs(i).RPDOcfg; %could make this a function call with i=input
            app.RPDO_RMmap.Data=app.RMPDOs(i).RPDOmap;
            app.TPDO_RMcfg.Data=app.RMPDOs(i).TPDOcfg;
            app.TPDO_RMmap.Data=app.RMPDOs(i).TPDOmap;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'PDOcfgRead';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Text = 'SaveAs';
            app.ResetRPDOTabletoDefaultButton = uibutton(app.UIFigure, 'push');
            app.ResetRPDOTabletoDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @ResetRPDOTabletoDefaultButtonPushed, true);
            app.ResetRPDOTabletoDefaultButton.Tooltip = {'Set tables back to default values '};
            app.ResetRPDOTabletoDefaultButton.Text = {'Reset RPDO'; 'Table to '; 'Default'};
            app.ReadPMRPDOButton = uibutton(app.UIFigure, 'push');
            app.ReadPMRPDOButton.ButtonPushedFcn = createCallbackFcn(app, @ReadPMRPDOButtonPushed, true);
            app.ReadPMRPDOButton.Text = {'Read PM'; 'RPDO'};
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RPDOPMTab = uitab(app.TabGroup);
            app.RPDOPMTab.Title = 'RPDO-PM';
            app.RPDOmapTable = uitable(app.RPDOPMTab);
            app.RPDOmapTable.ColumnName = {'PDO1'; 'PDO2'; 'PDO3'; 'PDO4'; 'PDO5'; 'PDO6'; 'PDO7'; 'PDO8'};
            app.RPDOmapTable.RowName = {};
            app.RPDOmapTable.ColumnEditable = true;
            app.x1600MappingLabel = uilabel(app.RPDOPMTab);
            app.x1600MappingLabel.Text = '0x1600 - Mapping';
            app.RPDOsettingsTable = uitable(app.RPDOPMTab);
            app.RPDOsettingsTable.ColumnName = {'PDO1'; 'PDO2'; 'PDO3'; 'PDO4'; 'PDO5'; 'PDO6'; 'PDO7'; 'PDO8'};
            app.RPDOsettingsTable.RowName = {};
            app.RPDOsettingsTable.ColumnEditable = true;
            app.x1400SettingsLabel = uilabel(app.RPDOPMTab);
            app.x1400SettingsLabel.Text = '0x1400 - Settings';
            app.PMRPDOLabel = uilabel(app.RPDOPMTab);
            app.PMRPDOLabel.Text = 'PM RPDO';
            app.TPDOPMTab = uitab(app.TabGroup);
            app.TPDOPMTab.Title = 'TPDO-PM';
            app.TPDOmapTable = uitable(app.TPDOPMTab);
            app.TPDOmapTable.ColumnName = {'PDO1'; 'PDO2'; 'PDO3'; 'PDO4'; 'PDO5'; 'PDO6'; 'PDO7'; 'PDO8'};
            app.TPDOmapTable.RowName = {};
            app.TPDOmapTable.ColumnEditable = true;
            app.x1A00MappingLabel = uilabel(app.TPDOPMTab);
            app.x1A00MappingLabel.Text = '0x1A00 - Mapping';
            app.TPDOsettingsTable = uitable(app.TPDOPMTab);
            app.TPDOsettingsTable.ColumnName = {'PDO1'; 'PDO2'; 'PDO3'; 'PDO4'; 'PDO5'; 'PDO6'; 'PDO7'; 'PDO8'};
            app.TPDOsettingsTable.RowName = {};
            app.TPDOsettingsTable.ColumnEditable = true;
            app.x1800SettingsLabel = uilabel(app.TPDOPMTab);
            app.x1800SettingsLabel.Text = '0x1800 - Settings';
            app.PMTPDOLabel = uilabel(app.TPDOPMTab);
            app.PMTPDOLabel.Text = 'PM TPDO';
            app.StimTab = uitab(app.TabGroup);
            app.StimTab.Title = 'Stim';
            app.TpdoTempAccPanel = uipanel(app.StimTab);
            app.TpdoTempAccPanel.Title = 'Tpdo Temp,Acc,';
            app.PGTpdomapping = uitable(app.TpdoTempAccPanel);
            app.PGTpdomapping.ColumnName = {'Mapping'};
            app.PGTpdomapping.RowName = {};
            app.PGTpdomapping.ColumnEditable = true;
            app.RpdoTestYPGPanel = uipanel(app.StimTab);
            app.RpdoTestYPGPanel.Title = 'Rpdo TestY PG';
            app.PGRpdomapping = uitable(app.RpdoTestYPGPanel);
            app.PGRpdomapping.ColumnName = {'Mapping'};
            app.PGRpdomapping.RowName = {};
            app.PGRpdomapping.ColumnEditable = true;
            app.PGRpdoID = uitable(app.RpdoTestYPGPanel);
            app.PGRpdoID.ColumnName = {'RPDO_ID'};
            app.PGRpdoID.RowName = {};
            app.PGRpdoID.ColumnEditable = true;
            app.FilteredAccelerometersLabel = uilabel(app.StimTab);
            app.FilteredAccelerometersLabel.Text = {'Filtered'; 'Accelerometers'};
            app.FilteredAccelerometersSwitch = uiswitch(app.StimTab, 'slider');
            app.FilteredAccelerometersSwitch.ValueChangedFcn = createCallbackFcn(app, @FilteredAccelerometersSwitchValueChanged, true);
            app.FilteredAccelerometersSwitch.Value = 'On';
            app.BPTab = uitab(app.TabGroup);
            app.BPTab.Title = 'BP';
            app.TpdoTempAccPanelBP = uipanel(app.BPTab);
            app.TpdoTempAccPanelBP.Title = 'Tpdo Temp,Acc,';
            app.BPTpdomapping = uitable(app.TpdoTempAccPanelBP);
            app.BPTpdomapping.ColumnName = {'Mapping'};
            app.BPTpdomapping.RowName = {};
            app.BPTpdomapping.ColumnEditable = true;
            app.RMPDOsTab = uitab(app.TabGroup);
            app.RMPDOsTab.Title = 'RM-PDOs';
            app.ReceiveLabel = uilabel(app.RMPDOsTab);
            app.ReceiveLabel.Text = 'Receive';
            app.SettingsLabel = uilabel(app.RMPDOsTab);
            app.SettingsLabel.Text = 'Settings';
            app.MappingsLabel = uilabel(app.RMPDOsTab);
            app.MappingsLabel.Text = 'Mappings';
            app.x1400Label = uilabel(app.RMPDOsTab);
            app.x1400Label.Text = '0x1400';
            app.RPDO_RMcfg = uitable(app.RMPDOsTab);
            app.RPDO_RMcfg.ColumnName = {'Node 1'; 'Node 2'; 'Node 3'; 'Node 4'; 'Node 5'; 'Node 6'; 'Node 9'};
            app.RPDO_RMcfg.RowName = {};
            app.RPDO_RMmap = uitable(app.RMPDOsTab);
            app.RPDO_RMmap.ColumnName = {'Node 1'; 'Node 2'; 'Node 3'; 'Node 4'; 'Node 5'; 'Node 6'; 'Node 9'};
            app.RPDO_RMmap.RowName = {};
            app.x1600Label = uilabel(app.RMPDOsTab);
            app.x1600Label.Text = '0x1600';
            app.RMTPDOsTab = uitab(app.TabGroup);
            app.RMTPDOsTab.Title = 'RM-TPDOs';
            app.TransmitLabel = uilabel(app.RMTPDOsTab);
            app.TransmitLabel.Text = 'Transmit';
            app.x1800Label = uilabel(app.RMTPDOsTab);
            app.x1800Label.Text = '0x1800';
            app.TPDO_RMcfg = uitable(app.RMTPDOsTab);
            app.TPDO_RMcfg.ColumnName = {'Node 1'; 'Node 2'; 'Node 3'; 'Node 4'; 'Node 5'; 'Node 6'; 'Node 9'};
            app.TPDO_RMcfg.RowName = {};
            app.TPDO_RMmap = uitable(app.RMTPDOsTab);
            app.TPDO_RMmap.ColumnName = {'Node 1'; 'Node 2'; 'Node 3'; 'Node 4'; 'Node 5'; 'Node 6'; 'Node 9'};
            app.TPDO_RMmap.RowName = {};
            app.x1A00Label = uilabel(app.RMTPDOsTab);
            app.x1A00Label.Text = '0x1A00';
            app.SettingsLabel_2 = uilabel(app.RMTPDOsTab);
            app.SettingsLabel_2.Text = 'Settings';
            app.MappingsLabel_2 = uilabel(app.RMTPDOsTab);
            app.MappingsLabel_2.Text = 'Mappings';
            app.ReadPMTPDOsButton = uibutton(app.UIFigure, 'push');
            app.ReadPMTPDOsButton.ButtonPushedFcn = createCallbackFcn(app, @ReadPMTPDOsButtonPushed, true);
            app.ReadPMTPDOsButton.Tooltip = {'Write n Tpdos to PM'};
            app.ReadPMTPDOsButton.Text = {'Read PM'; 'TPDOs'};
            app.PMODSaveButton = uibutton(app.UIFigure, 'push');
            app.PMODSaveButton.ButtonPushedFcn = createCallbackFcn(app, @PMODSaveButtonPushed, true);
            app.PMODSaveButton.Tooltip = {'Save PM OD values to retsore'};
            app.PMODSaveButton.Text = 'PM OD Save';
            app.ReadRemoteModulesButton = uibutton(app.UIFigure, 'push');
            app.ReadRemoteModulesButton.ButtonPushedFcn = createCallbackFcn(app, @ReadRemoteModulesButtonPushed, true);
            app.ReadRemoteModulesButton.Tooltip = {'Write stim module TPDOs based on stmi nodes listed'};
            app.ReadRemoteModulesButton.Text = {'Read  Remote'; 'Module PDOs'};
            app.PMversionPanel = uipanel(app.UIFigure);
            app.PMversionPanel.Title = 'PM version';
            app.TpdosEditFieldLabel = uilabel(app.PMversionPanel);
            app.TpdosEditFieldLabel.Text = 'Tpdos';
            app.TpdosEditField = uieditfield(app.PMversionPanel, 'numeric');
            app.TpdosEditField.Editable = 'off';
            app.TpdosEditField.Tooltip = {'Number of  TPDOs to write, based on PM version'};
            app.TpdosEditField.Value = 6;
            app.PMVersionButton = uibutton(app.PMversionPanel, 'push');
            app.PMVersionButton.ButtonPushedFcn = createCallbackFcn(app, @PMVersionButtonPushed, true);
            app.PMVersionButton.Tooltip = {'Read PM version'};
            app.PMVersionButton.Text = {'PM'; 'Version'};
            app.PMverEditFieldLabel = uilabel(app.PMversionPanel);
            app.PMverEditFieldLabel.Text = 'PMver';
            app.PMverEditField = uieditfield(app.PMversionPanel, 'numeric');
            app.ResetTPDOTabletoDefaultButton = uibutton(app.UIFigure, 'push');
            app.ResetTPDOTabletoDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @ResetTPDOTabletoDefaultButtonPushed, true);
            app.ResetTPDOTabletoDefaultButton.Tooltip = {'Set table back to default values'};
            app.ResetTPDOTabletoDefaultButton.Text = {'Reset TPDO'; 'Table to '; 'Default'};
            app.MessagesTextAreaLabel = uilabel(app.UIFigure);
            app.MessagesTextAreaLabel.Text = 'Messages';
            app.Messages = uitextarea(app.UIFigure);
            app.ReadingPanel = uipanel(app.UIFigure);
            app.ReadingPanel.Title = 'Reading';
            app.ReadingLampLabel = uilabel(app.ReadingPanel);
            app.ReadingLampLabel.Text = 'Reading';
            app.ReadingLamp = uilamp(app.ReadingPanel);
            app.ReadingLamp.Enable = 'off';
            app.indLabel = uilabel(app.ReadingPanel);
            app.indLabel.Text = 'ind';
            app.test1 = uieditfield(app.ReadingPanel, 'numeric');
            app.test1.Editable = 'off';
            app.nodeLabel = uilabel(app.ReadingPanel);
            app.nodeLabel.Text = 'node';
            app.test2 = uieditfield(app.ReadingPanel, 'numeric');
            app.test2.Editable = 'off';
            app.NodeLabel = uilabel(app.UIFigure);
            app.NodeLabel.Text = 'Node';
            app.NodeSelect = uidropdown(app.UIFigure);
            app.NodeSelect.Items = {'1', '2', '3', '4', '5', '6', '9'};
            app.NodeSelect.ValueChangedFcn = createCallbackFcn(app, @NodeSelectValueChanged, true);
            app.NodeSelect.Value = '1';
            app.NodeListStim = uitextarea(app.UIFigure);
            app.NodeListStim.Value = {'1 2 3 4 5 6'};
            app.StimNodesLabel = uilabel(app.UIFigure);
            app.StimNodesLabel.Text = {'Stim'; 'Nodes'};
            app.NodeListBP = uitextarea(app.UIFigure);
            app.NodeListBP.Value = {'9'};
            app.BPNodesLabel = uilabel(app.UIFigure);
            app.BPNodesLabel.Text = {'BP'; ' Nodes'};
            app.UserIDEditField = uieditfield(app.UIFigure, 'text');
            app.UserIDEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pdoConfigRead(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end