classdef phoneAppFunctions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        phoneAppEmulation             matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        OpenMenu                      matlab.ui.container.Menu
        SaveMenu                      matlab.ui.container.Menu
        SaveAsMenu                    matlab.ui.container.Menu
        ShutdownPMButton              matlab.ui.control.Button
        MessagesPanel                 matlab.ui.container.Panel
        MessagesLabel                 matlab.ui.control.Label
        ClearMessageButton            matlab.ui.control.Button
        Messages                      matlab.ui.control.TextArea
        DiagnosticPanel               matlab.ui.container.Panel
        ScriptsPanel                  matlab.ui.container.Panel
        ScriptStatusButton            matlab.ui.control.Button
        StartupButton                 matlab.ui.control.Button
        DisableButton                 matlab.ui.control.Button
        EnableButton                  matlab.ui.control.Button
        MESTestDataButton             matlab.ui.control.Button
        activeFGButton                matlab.ui.control.Button
        NetOffButton                  matlab.ui.control.Button
        NetOnButton                   matlab.ui.control.Button
        CfgButton                     matlab.ui.control.Button
        PMVersionButton               matlab.ui.control.Button
        DisplayPanel                  matlab.ui.container.Panel
        DisplaySwitch                 matlab.ui.control.Switch
        HandTogglePanel               matlab.ui.container.Panel
        UpButton                      matlab.ui.control.Button
        MidButton                     matlab.ui.control.Button
        DnButton                      matlab.ui.control.Button
        ReadCurrentHTButton           matlab.ui.control.Button
        ToggleButton_2                matlab.ui.control.Button
        SetToggleOptionDropDown       matlab.ui.control.DropDown
        SetToggleOptionDropDownLabel  matlab.ui.control.Label
        TrunkPanel_2                  matlab.ui.container.Panel
        MaxDefaultButton              matlab.ui.control.Button
        MaxUpButton                   matlab.ui.control.Button
        MaxDownButton                 matlab.ui.control.Button
        MaxCmdEditField               matlab.ui.control.NumericEditField
        MaxCmdEditFieldLabel          matlab.ui.control.Label
        MaxLevelEditField             matlab.ui.control.NumericEditField
        MaxLevelLabel                 matlab.ui.control.Label
        AuxArmPanel                   matlab.ui.container.Panel
        Aux1ToggleButton              matlab.ui.control.Button
        SetAuxOptionDropDown          matlab.ui.control.DropDown
        SetAuxOptionLabel             matlab.ui.control.Label
        ReadCurrentAuxButton          matlab.ui.control.Button
        Aux1UpButton                  matlab.ui.control.Button
        Aux1DownButton                matlab.ui.control.Button
        AuxOnOffButton                matlab.ui.control.Button
        ExercisePanel_2               matlab.ui.container.Panel
        RegEditFieldLabel             matlab.ui.control.Label
        RegtEditField                 matlab.ui.control.NumericEditField
        ExPattern_2                   matlab.ui.control.NumericEditField
        ExPattern_1                   matlab.ui.control.NumericEditField
        StateEditField                matlab.ui.control.NumericEditField
        StateEditFieldLabel           matlab.ui.control.Label
        SetEditField                  matlab.ui.control.NumericEditField
        SetEditFieldLabel             matlab.ui.control.Label
        CycleEditField                matlab.ui.control.NumericEditField
        CycleEditFieldLabel           matlab.ui.control.Label
        CommandPanel                  matlab.ui.container.Panel
        TrunkAux4Label                matlab.ui.control.Label
        HandAux1Label                 matlab.ui.control.Label
        CommandLabel                  matlab.ui.control.Label
        PatternLabel                  matlab.ui.control.Label
        HandCommand                   matlab.ui.control.NumericEditField
        Aux1Command                   matlab.ui.control.NumericEditField
        TrunkCommand                  matlab.ui.control.NumericEditField
        Aux4Command                   matlab.ui.control.NumericEditField
        Aux4Pattern                   matlab.ui.control.NumericEditField
        TrunkPattern                  matlab.ui.control.NumericEditField
        Aux1Pattern                   matlab.ui.control.NumericEditField
        HandPattern                   matlab.ui.control.NumericEditField
        LogicPanel                    matlab.ui.container.Panel
        LockButton                    matlab.ui.control.Button
        TwitchEditField               matlab.ui.control.NumericEditField
        TwitchEditFieldLabel          matlab.ui.control.Label
        LengthEditField               matlab.ui.control.NumericEditField
        LengthEditFieldLabel          matlab.ui.control.Label
        MESPanel                      matlab.ui.container.Panel
        Label                         matlab.ui.control.Label
        WFLASFLabel                   matlab.ui.control.Label
        ASF_2                         matlab.ui.control.NumericEditField
        ASF_1                         matlab.ui.control.NumericEditField
        WFL_2                         matlab.ui.control.NumericEditField
        WFL_1                         matlab.ui.control.NumericEditField
        SystemPanel                   matlab.ui.container.Panel
        RSSIEditField                 matlab.ui.control.NumericEditField
        RSSIEditFieldLabel            matlab.ui.control.Label
        BatteriesEditField            matlab.ui.control.NumericEditField
        BatteriesEditFieldLabel       matlab.ui.control.Label
        PMTempEditField               matlab.ui.control.NumericEditField
        PMTempEditFieldLabel          matlab.ui.control.Label
        NetworkLamp                   matlab.ui.control.Lamp
        NetworkLampLabel              matlab.ui.control.Label
        ModeEditField                 matlab.ui.control.NumericEditField
        ModeEditFieldLabel            matlab.ui.control.Label
        StatusPanel                   matlab.ui.container.Panel
        ScanIntervalEditField         matlab.ui.control.NumericEditField
        scanIntLabel                  matlab.ui.control.Label
        ScanLED                       matlab.ui.control.Lamp
        ScanButton                    matlab.ui.control.StateButton
        GetStatusButton               matlab.ui.control.Button
        CoughPanel                    matlab.ui.container.Panel
        CoughButton                   matlab.ui.control.Button
        EnableCoughButton             matlab.ui.control.Button
        StopPanel                     matlab.ui.container.Panel
        StopTrunkButton               matlab.ui.control.Button
        StopBothButton                matlab.ui.control.Button
        StopHandButton                matlab.ui.control.Button
        TrunkPanel                    matlab.ui.container.Panel
        TrunkButton5                  matlab.ui.control.Button
        TrunkButton4                  matlab.ui.control.Button
        TrunkButton3                  matlab.ui.control.Button
        TrunkButton2                  matlab.ui.control.Button
        TrunkButton1                  matlab.ui.control.Button
        ExercisePanel                 matlab.ui.container.Panel
        Exercise2Button5              matlab.ui.control.Button
        Exercise2Button4              matlab.ui.control.Button
        Exercise2Button3              matlab.ui.control.Button
        Exercise2Button2              matlab.ui.control.Button
        ExerciseButton1               matlab.ui.control.Button
        HandPanel                     matlab.ui.container.Panel
        HandButton5                   matlab.ui.control.Button
        HandButton4                   matlab.ui.control.Button
        HandButton3                   matlab.ui.control.Button
        HandButton2                   matlab.ui.control.Button
        HandButton1                   matlab.ui.control.Button
        PhoneAppFunctionsLabel        matlab.ui.control.Label
    end


    properties (Access = private)

    end

    properties (Access = public)
        nnp=[]; % nnpapi
        A57=zeros(150,1); % PM 1F57 array
        PA53=zeros(50,1); % Phone App Array at 1F53 starting index 1C
        filename57=[];
        pathname=[];
        updateTimer = [];
        lastUpdateTime = 0;
        callingFigure=[]; %handle to calling figure
    end

    methods (Access = public)

        function ClearMessages(app)
            app.Messages.Value=''; % clear messages
        end

        function TopMessage(app,message)
            app.Messages.Value=vertcat(message, string(app.Messages.Value)); % put new message on top
        end

        function success = startHand(app,pat)
            % if either message fails, then consider both failed
            resp=app.nnp.write(7,'1F53',22,uint8(pat),'uint8'); % immediate pattern set
            success=(resp==0);
            if resp==0
                resp2=app.nnp.write(7,'1F53',4,uint8(5),'uint8');
                if resp2==0
                    message=['Start ADL',num2str(pat)];
                else
                    message=['Start ADL',num2str(pat),' failed'];
                end
            else
                message=['Start ADL',num2str(pat),' failed'];
            end
            app.TopMessage(message);
        end

        function success = stopHand(app)
            resp=app.nnp.write(7,'1F53',4,uint8(8),'uint8');
            success=(resp==0);
            if resp==0
                message='Stop ADL';
            else
                message=['Stop ADL',' failed'];
            end
            app.TopMessage(message);
        end

        function success = startTrunk(app,pat)
            resp=app.nnp.write(7,'1F57',99,uint8(pat),'uint8'); % immediate pattern set
            success=(resp==0);
            if resp==0
                resp2=app.nnp.write(7,'1F53',4,uint8(1),'uint8');
                if resp2==0
                    message=['Start Trunk ',num2str(pat)];
                else
                    message=['Start ADL',num2str(pat),' failed'];
                end
            else
                message=['Start ADL',num2str(pat),' failed'];
            end
            app.TopMessage(message);
        end

        function success = stopTrunk(app)
            resp=app.nnp.write(7,'1F53',4,uint8(7),'uint8');
            success=(resp==0);
            if resp==0
                message='Stop Trunk';
            else
                message=['Stop Trunk',' failed'];
            end
            app.TopMessage(message);
        end

        function success =  startExercise(app,pat)
            %             Ext =  action
            %             1 = 6
            %             2 = 2
            %             3 = 3
            %             4 = 4
            %             5 = used by standing exercise via transfer assist button in phone
            if pat>0 && pat<5
                if pat==1
                    act=6;
                else
                    act=pat;
                end
                resp2=app.nnp.write(7,'1F53',4,uint8(act),'uint8');
                if resp2==0
                    message=['Exercise ',num2str(pat)];
                else
                    message=['Exercise',num2str(pat),' failed'];
                end
            else
                message=['Exercise',' failed'];
            end
            app.TopMessage(message);
        end

        function success = stopAll(app)
            resp=app.nnp.write(7,'1F53',4,uint8(9),'uint8');
            success=(resp==0);
            if resp==0
                message='Stop All';
            else
                message=['Stop All',' failed'];
            end
            app.TopMessage(message);
        end
    end

    methods (Access = private)

        function updateDisplays(app, obj, event) % do I need obj, event if used with timer function?
            % read array from 1f53
            app.ScanLED.Color = [0.80,0.80,0.80]; % to make LED blink slightly each cycle
            resp=[];
            resp=app.nnp.read(7,'1F53',28,50,'uint8');
            pause(.05);
            if(length(resp)==50) %valid packet
                % make LED blink green by turning green while updating
                app.ScanLED.Color = [0,1,0];    %normal [0.80,0.80,0.80], green,[0,1,0], red [ 1,0,0]
                app.PA53(1:50)=double(resp); % first replace all the value with double, then go back to the four signed cases and replace
                % replace the signed cases
                app.PA53(1)=double(typecast(resp(1:2),'uint16'))/10; %PM Temp deg C/10
                app.PA53(2)=app.PA53(1); % make duplicate copy to make indexing easier
                app.PA53(3)=double(typecast(resp(3:4),'uint16'))/100; %V/100
                app.PA53(4)=app.PA53(3); % make duplicate copy to make indexing easier
                app.PA53(33)=double(typecast(resp(33:34),'int16')); % Diff5 MES1
                app.PA53(34)=app.PA53(33); % make duplicate copy to make indexing easier
                app.PA53(35)=double(typecast(resp(35:36),'int16')); % Diff5 MES1
                app.PA53(36)=app.PA53(35); % make duplicate copy to make indexing easier
                % Having updated the array, now fill in the blanks
                app.PMTempEditField.Value=app.PA53(1); % PM Temp HB
                app.BatteriesEditField.Value=app.PA53(3); % VbattAvg LB


                app.HandCommand.Value=app.PA53(5); % Command Hand
                app.Aux1Command.Value=app.PA53(6); % Command A1
                %.Value=app.PA53(7); % Command A2
                app.TrunkCommand.Value=app.PA53(8); % Command Trunk
                %.Value=app.PA53(9); % Command A3
                app.Aux4Command.Value=app.PA53(10); % Command A4
                app.ModeEditField.Value=app.PA53(11); % PM Node State
                if app.PA53(12)>0 % Network On
                    app.NetworkLamp.Color=[0,1,0];
                else
                    app.NetworkLamp.Color=[0.8,0.8,0.8];
                end
                % .Value=app.PA53(13); % UE activity
                % .Value=app.PA53(14); % LE activity
                switch app.PA53(15)  % LockStatus
                    case 0 % unlocked
                        app.LockButton.BackgroundColor=[0.96,0.96,0.96];
                    case 1 % transition
                        app.LockButton.BackgroundColor=[1,1,0]; % yellow for transition
                    case 2 % locked
                        app.LockButton.BackgroundColor=[1,0,0]; %red for locked
                end
                % .Value=app.PA53(16); % MES1 scaled
                % .Value=app.PA53(17); % MES2  scaled
                % .Value=app.PA53(18); % ToneIndex
                app.WFL_1.Value=app.PA53(19); % MES1 WFL
                app.WFL_2.Value=app.PA53(20); % MES2 WFL

                app.HandPattern.Value=app.PA53(21); %  Pat_Hand
                app.Aux1Pattern.Value=app.PA53(22); %  Pat_Aux1
                % .Value=app.PA53(23); %  Pat_Aux2
                app.TrunkPattern.Value=app.PA53(24); %  Pat_Trunk
                % .Value=app.PA53(25); %  Pat_Aux3
                app.Aux4Pattern.Value=app.PA53(26); %  Pat_Aux4
                if app.PA53(26)>0 % currently this means cough mode (may want to tie this to the button push rather than scan)
                    app.CoughPanel.BackgroundColor=[0.85,1.00,0.81];
                else
                    app.CoughPanel.BackgroundColor=[.94,.94,.94]; % not active (not sure I want this updating every cycle)
                end
                % if in exercise mode, then need to use exercise patterns for the above
                % if app.PA53(13)==2 % UE activity is exercise
                app.ExPattern_1.Value=app.PA53(27); % Ex_Pat1
                app.ExPattern_2.Value=app.PA53(28); % Ex_Pat2

                app.ASF_1.Value=app.PA53(29); % ASF1
                app.ASF_2.Value=app.PA53(30); % ASF2
                app.LengthEditField.Value=app.PA53(31); % HLCount1
                % .Value=app.PA53(32); % HLCount2
                % .Value=app.PA53(33); % Diff5 MES1 (LB)
                % .Value=app.PA53(34); % Diff5 MES1 (HB)
                % .Value=app.PA53(35); % Diff5 MES2 (LB)
                % .Value=app.PA53(36); % Diff5 MES2 (HB)
                app.TwitchEditField.Value=app.PA53(37); % Twitch MES1 SLD
                % .Value=app.PA53(38); % Twitch MES2 SLD
                % .Value=app.PA53(39); % Tsize1
                %.Value=app.PA53(40); % Tlength1
                % .Value=app.PA53(41); % Tsize2
                % .Value=app.PA53(42); % Tlength2
                app.MaxLevelEditField.Value=app.PA53(43); % Trunk Max Index
                app.MaxCmdEditField.Value=app.PA53(44); % Trunk Max Cmd
                app.CycleEditField.Value=app.PA53(45); % Cycle
                app.SetEditField.Value=app.PA53(46); % Set
                app.RegtEditField.Value=app.PA53(47); % Reg
                app.StateEditField.Value=app.PA53(48); % State
                % .Value=app.PA53(49); % Clock Set
                % .Value=app.PA53(50); % sCommand

            else
                % make LED blink red
                app.ScanLED.Color = [1,0,0];    %normal [0.80,0.80,0.80], green,[0,1,0], red [ 1,0,0]
            end
            %  pause(.1);
            %  app.ScanLED.Color = [0.80,0.80,0.80];    %normal [0.80,0.80,0.80], green,[0,1,0], red [ 1,0,0]
        end

        function startUpdate(app)
            app.TopMessage('starting timer');
            start(app.updateTimer);
            app.SystemPanel.BackgroundColor=[0.90,0.97,0.97];
            app.LogicPanel.BackgroundColor=[0.99,0.99,0.85];
            app.MESPanel.BackgroundColor=[0.85,1.00,0.81];
            app.CommandPanel.BackgroundColor=[0.99,0.89,0.89];
            app.ExercisePanel_2.BackgroundColor=[0.82,0.97,0.93];
        end

        function stopUpdate(app)
            app.TopMessage('stopping timer');
            stop(app.updateTimer);
            app.ScanLED.Color = [0.80,0.80,0.80];    %normal [0.80,0.80,0.80], green,[0,1,0], red [ 1,0,0]
            app.LockButton.BackgroundColor=[0.96,0.96,0.96];
            app.NetworkLamp.Color=[0.8,0.8,0.8];
            app.SystemPanel.BackgroundColor=[0.94,0.94,0.94];
            app.LogicPanel.BackgroundColor=[0.94,0.94,0.94];
            app.MESPanel.BackgroundColor=[0.94,0.94,0.94];
            app.CommandPanel.BackgroundColor=[0.94,0.94,0.94];
            app.ExercisePanel_2.BackgroundColor=[0.94,0.94,0.94];
            app.CoughPanel.BackgroundColor=[0.94,0.94,0.94];
        end
        
        function getActiveFG(app,nodes)
            % read in active FG in the nodes listed in array nodes
            str=sprintf('Active FG\n');
            for i=1:size(nodes,2)
                FGstr='';
                resp=app.nnp.read(nodes(i),'3200',2,'uint8');
                if length(resp)==4
                    FGstr=sprintf('%d ',resp);
                    str=[str,sprintf('\nNode %d: %s',nodes(i),FGstr)];
                end
            end
            %
            h = msgbox(str, 'ActiveFG', 'modal');
            uiwait(h);
        end
    
        
        function StartStand(app)
            % I may want to first check that other patterns not running
            % first.
             resp=app.nnp.write(7,'1F53',4,uint8(23),'uint8');
            success=(resp==0);
            if resp==0
                message='Start Stand';
            else
                message=['Stand',' failed'];
            end
            app.TopMessage(message);
        end
        
        function ShowdownPM(app)
            resp=app.nnp.write(7,'1F53',4,uint8(11),'uint8');
            success=(resp==0);
            if resp==0
                message='Shutdown PM';
            else
                message=['Shutdown PM',' failed'];
            end
            app.TopMessage(message);
        
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin<2
                NNP=[];
            else
                NNP=varargin{1};
            end
            if isempty(NNP)
                app.nnp = NNPAPI();
                if isempty(app.nnp.port)
                    msgbox('No port chosen for NNP Access Point')
                    delete(app.nnp)
                    return
                end
            else
                app.nnp = NNP;
            end
            if nargin>2
                app.callingFigure=varargin{2};
            end

            app.updateTimer=timer(); %start with default timer
            app.updateTimer.Period=app.ScanIntervalEditField.Value;
            app.updateTimer.ExecutionMode='fixedRate';
            app.updateTimer.BusyMode='queue'; %may want to change this to another option
            %             app.updateTimer.BusyMode='drop'; %may want to change this to another option
            app.updateTimer.TimerFcn=@app.updateDisplays; %update the displays each timer cycle
            % read in variables
            app.SetToggleOptionDropDown.ItemsData=[0,1,2,3]; % so that I don't need to convert to/from strings
            app.SetAuxOptionDropDown.ItemsData=[0,1]; % so that I don't need to convert to/from strings
        end

        % Button pushed function: PMVersionButton
        function PMVersionButtonPushed(app, event)
            resp=double(app.nnp.read(7,'1018',3,'uint32')); % read revision number to test communications
            if ~isempty(resp)
                dmessage=sprintf('PM version %d',resp);
            else
                dmessage='PM not read';
            end
            app.TopMessage(dmessage);

        end

        % Button pushed function: ClearMessageButton
        function ClearMessageButtonPushed(app, event)
            app.ClearMessages();
        end

        % Button pushed function: NetOnButton
        function NetOnButtonPushed(app, event)
            if ~app.nnp.networkOn() % will put into waiting, returns true if successful
                app.TopMessage('Network On failed');
                return;
            else
                app.TopMessage('Network On');
            end
        end

        % Button pushed function: NetOffButton
        function NetOffButtonPushed(app, event)
            if ~app.nnp.networkOff() % will put into waiting, returns true if successful
                app.TopMessage('Network Off failed');
                return;
            else
                app.TopMessage('Network Off');
            end
        end

        % Button pushed function: HandButton1
        function HandButton1Pushed(app, event)
            app.startHand(1);
        end

        % Button pushed function: HandButton2
        function HandButton2Pushed(app, event)
            app.startHand(2);
        end

        % Button pushed function: HandButton3
        function HandButton3Pushed(app, event)
            app.startHand(3);
        end

        % Button pushed function: HandButton4
        function HandButton4Pushed(app, event)
            app.startHand(4);
        end

        % Button pushed function: HandButton5
        function HandButton5Pushed(app, event)
            app.startHand(5);
        end

        % Button pushed function: ExerciseButton1
        function ExerciseButton1Pushed(app, event)
            app.startExercise(1);
        end

        % Button pushed function: Exercise2Button2
        function Exercise2Button2Pushed(app, event)
            app.startExercise(2);
        end

        % Button pushed function: Exercise2Button3
        function Exercise2Button3Pushed(app, event)
            app.startExercise(3);
        end

        % Button pushed function: Exercise2Button4
        function Exercise2Button4Pushed(app, event)
            app.startExercise(4);
        end

        % Button pushed function: Exercise2Button5
        function Exercise2Button5Pushed(app, event)
            app.startExercise(5);
        end

        % Button pushed function: TrunkButton1
        function TrunkButton1Pushed(app, event)
            app.startTrunk(1);
        end

        % Button pushed function: TrunkButton2
        function TrunkButton2Pushed(app, event)
            app.startTrunk(2);
        end

        % Button pushed function: TrunkButton3
        function TrunkButton3Pushed(app, event)
            app.startTrunk(3);
        end

        % Button pushed function: TrunkButton4
        function TrunkButton4Pushed(app, event)
            app.startTrunk(4);
        end

        % Button pushed function: TrunkButton5
        function TrunkButton5Pushed(app, event)
            % Action 23, Standing Transfer            
            % app.startTrunk(5);
            % Standing Transfer or Exercise 5 depending upon 1F57.83
            % (Exercise if equal to 1)
            app.StartStand();
        end

        % Button pushed function: StopHandButton
        function StopHandButtonPushed(app, event)
            app.stopHand();
        end

        % Button pushed function: StopBothButton
        function StopBothButtonPushed(app, event)
            app.stopAll();
        end

        % Button pushed function: StopTrunkButton
        function StopTrunkButtonPushed(app, event)
            app.stopTrunk();
        end

        % Button pushed function: EnableButton
        function EnableButtonPushed(app, event)
            app.nnp.enterWaiting();
            response=app.nnp.nmt(7, '8E'); %enable scripts
            if response==hex2dec('8E')
                message='Scripts Enabled';
            else
                message=app.nnp.lastError;
            end
            app.TopMessage(message);
        end

        % Button pushed function: DisableButton
        function DisableButtonPushed(app, event)
            app.nnp.enterWaiting();
            response=app.nnp.nmt(7, '8F'); %disable scripts
            if response==hex2dec('8F')
                message='Scripts Disabled';
            else
                message=app.nnp.lastError;
            end
            app.TopMessage(message);
        end

        % Button pushed function: StartupButton
        function StartupButtonPushed(app, event)
            response=app.nnp.nmt(7, '8E'); %enable scripts
            if response==hex2dec('8E')
                message='Scripts Enabled';
                response=app.nnp.nmt(7,'B3',7); % Run startup script
                if response==hex2dec('B3')
                    message='Scripts enabled and Startup Ran';

                else
                    message=['Scripts Enabled, but ',app.nnp.lastError];

                end
            else
                message=app.nnp.lastError;
            end
            app.TopMessage(message);
        end

        % Button pushed function: EnableCoughButton
        function EnableCoughButtonPushed(app, event)
            resp2=app.nnp.write(7,'1F53',4,uint8(27),'uint8');
            if resp2==0
                message='Enable Cough';
            else
                message=['Enable Cough',' failed'];
            end
            app.TopMessage(message);
        end

        % Button pushed function: CoughButton
        function CoughButtonPushed(app, event)
            resp2=app.nnp.write(7,'1F53',113,uint8(1),'uint8');
            if resp2==0
                message='Cough';
            else
                message=['Cough',' failed'];
            end
            app.TopMessage(message);
        end

        % Button pushed function: GetStatusButton
        function GetStatusButtonPushed(app, event)
            [modes, temp, vsys, net, rssi] = app.nnp.getStatus();
            if ~isempty(modes)
                if net
                    str = sprintf('Network is ON');
                else
                    str = sprintf('Network is OFF');
                end
                str = [str, sprintf('\n\nPM Temp: %4.1f⁰C\nRSSI: %4.1fdBm\nVSYS: %4.1fV\nPM mode: %s\n\n', temp, rssi, vsys, modes{7})];
                if net
                    str = [str, sprintf('Note: Nodes not listed on network may be in bootloader mode.\nNodes also may take up to 2s to update.\n\n')];
                    for i=[1:6 8:length(modes)]
                        str=[str, sprintf('Node %2d: %s\n', i, modes{i})];
                    end
                else
                    str = [str,'Note: Nodes not listed because network is off'];
                end

                h = msgbox(str, 'Get Status', 'modal');
                uiwait(h);
            else
                h = msgbox('Could not get Status', 'Get Status Error', 'error', 'modal');
                uiwait(h);
            end

        end

        % Value changed function: ScanButton
        function ScanButtonValueChanged(app, event)
            value = app.ScanButton.Value;
            if value
                app.ScanButton.BackgroundColor=[0,1,0];
                app.updateTimer.Period=app.ScanIntervalEditField.Value;
                app.ScanIntervalEditField.Enable = 'off';
                app.GetStatusButton.Enable = 'off';
                app.ScriptStatusButton.Enable = 'off';
                app.activeFGButton.Enable = 'off';
                app.startUpdate();  % start updateDisplay

            else
                app.ScanButton.BackgroundColor=[0.96,0.96,0.96];
                % stop updateDisplay
                app.stopUpdate();
                app.ScanIntervalEditField.Enable = 'on';
                app.GetStatusButton.Enable = 'on';
                app.ScriptStatusButton.Enable = 'on';  
                app.activeFGButton.Enable = 'on';
            end


        end

        % Button pushed function: LockButton
        function LockButtonPushed(app, event)
            resp2=app.nnp.write(7,'1F53',4,uint8(26),'uint8');
            if resp2==0
                message='Lock Toggle';
            else
                message='Lock Toggle  failed';
            end
            app.TopMessage(message);
        end

        % Button pushed function: MaxDownButton
        function MaxDownButtonPushed(app, event)
            resp2=app.nnp.write(7,'1F53',4,uint8(22),'uint8');
            if resp2==0
                message=['Decrease Max'];
            else
                message=['Decrease Max',' failed'];
            end
            app.TopMessage(message);
        end

        % Button pushed function: MaxDefaultButton
        function MaxDefaultButtonPushed(app, event)
            resp2=app.nnp.write(7,'1F53',4,uint8(20),'uint8');
            if resp2==0
                message=['Default Max'];
            else
                message=['Default Max',' failed'];
            end
            app.TopMessage(message);
        end

        % Button pushed function: MaxUpButton
        function MaxUpButtonPushed(app, event)
            resp2=app.nnp.write(7,'1F53',4,uint8(21),'uint8');
            if resp2==0
                message=['Increase Max'];
            else
                message=['Increase Max',' failed'];
            end
            app.TopMessage(message);
        end

        % Button pushed function: AuxOnOffButton
        function AuxOnOffButtonPushed(app, event)
            resp2=app.nnp.write(7,'1F53',4,uint8(12),'uint8');
            if resp2==0
                message=['Aux Start'];
            else
                message=['Aux Start',' failed'];
            end
            app.TopMessage(message);
        end

        % Button pushed function: Aux1DownButton
        function Aux1DownButtonPushed(app, event)
            resp2=app.nnp.write(7,'1F53',19,uint8(0),'uint8'); % Aux 1 ramp down if in ramp mode
            if resp2==0

            else
                message='Aux1 down failed';
                app.TopMessage(message);
            end
        end

        % Button pushed function: Aux1UpButton
        function Aux1UpButtonPushed(app, event)
            resp2=app.nnp.write(7,'1F53',19,uint8(1),'uint8'); % Aux 1 ramp up if in ramp mode
            if resp2==0

            else
                message='Aux1 up failed';
                app.TopMessage(message);
            end
        end

        % Button pushed function: Aux1ToggleButton
        function Aux1ToggleButtonPushed(app, event)
            resp2=app.nnp.write(7,'1F53',16,uint8(1),'uint8'); % Aux 1 toggle if in ramp mode
            if resp2==0

            else
                message='Aux1 Toggle failed';
                app.TopMessage(message);
            end
        end

        % Close request function: phoneAppEmulation
        function phoneAppEmulationCloseRequest(app, event)
            if ishandle(app.callingFigure) % check that is is valid
                figure(app.callingFigure); % bring to front
            end
            delete(app)

        end

        % Button pushed function: ScriptStatusButton
        function ScriptStatusButtonPushed(app, event)
            % display a simple list of scripts which are currently running
            % return error if not all scriptControls are read
            % from scriptedit.mlapp:
            %should call populateLibraryTable before callng this
            %Can only read 50 bytes at a time so no more than 12 script controls
            resp = app.nnp.read(7, '1f51', 1,12, 'uint32'); %read script 1-12 controls
            if length(resp) ~= 12
                return
            else
                scriptControl(1:12) = double(resp);
            end
            resp = app.nnp.read(7, '1f51', 13,12, 'uint32'); %read script 13-24 controls
            if length(resp) ~= 12
                return
            else
                scriptControl(13:24) = double(resp);
            end
            resp =  app.nnp.read(7, '1f51', 25,1, 'uint32'); %read script 25 controls
            if length(resp) ~= 1
                return
            else
                scriptControl(25) = double(resp);
            end


            % I may use order in the second pass on this to put them in the order of
            % execution
            % order = [];
            % resp = app.nnp.read(7, '1f56', 1, 'uint8'); %read script order
            % if length(resp) == 25
            %     order = double(resp);
            % end

            %Script Controls bit definitions:
            % bit  0   : 1=run once
            % bit  1   : 1=run continuous, 0=halt
            % bit  2   : 1 = reinit global var table
            % bit  3-7 : reserved for future use
            % bit  8-15: script ID
            % bit 16-23: reserved for future use
            % bit 24-30: error code
            % bit 31   : 1= run once successful execution

            %Colors:
            % green: if run continuous (run = 2)
            % blue: run once complete (run = 0, runresult = 1)
            % yellow: run once not complete running (run = 1, runresult = 0)
            % red: error
            % white: stopped
            % gray: unused

            % for the first pass, I just want a list of SP running continuous
            % in this case bit 1=1,  otherwise ignore
            running=[];
            n=0;
            for row=1:25 %using row because they were in table in mlapp
                run = bitand(scriptControl(row), hex2dec('03'));
                %                 fprintf('sp %d, run %d\n',row,run);
                if run==2
                    n=n+1;
                    running(n)=row;
                end
            end
            if n>0
                runninglist=sprintf('%d ',running); %string with space between
            else
                runninglist=sprintf('no scripts running');
            end
            % first pass I will write to command window
            dmessage=sprintf('sp = %s\n',runninglist);
            app.TopMessage(dmessage);
        end

        % Button pushed function: ReadCurrentHTButton
        function ReadCurrentHTButtonPushed(app, event)
            % Read the parameter 1F57.6B from PM and set the dropdown box accordingly.
            % 0=none, 1=button only, 2=include twitch close, twitch close, 3=include twitch close, doublet open.
            resp = app.nnp.read(7,'1F57',107,1,'uint8');
            if length(resp) ~= 1
                message='Read Command Toggle Option failed';
                app.TopMessage(message);
                return
            else
                message='Read Com Toggle Option';
                app.TopMessage(message);
                app.SetToggleOptionDropDown.Value=resp;
                app.SetToggleOptionDropDown.Enable='on';
                if resp>0
                    app.ToggleButton_2.Enable='on';
                    app.DnButton.Enable='on';
                    app.MidButton.Enable='on';
                    app.UpButton.Enable='on';
                else
                    app.ToggleButton_2.Enable='off';
                    app.DnButton.Enable='off';
                    app.MidButton.Enable='off';
                    app.UpButton.Enable='off';
                end
            end

        end

        % Value changed function: SetToggleOptionDropDown
        function SetToggleOptionDropDownValueChanged(app, event)
            value = app.SetToggleOptionDropDown.Value;
            % When dropdown is changed, send the new value to 1F57.6B (107), but
            % first send 00 to 1F53.51 (81)
            if value>0
                resp=app.nnp.write(7,'1F53',81,uint8(2),'uint8');
                if resp==0
                    % may want to include message here
                end
            end
            itemString=app.SetToggleOptionDropDown.Items{value+1}; % add 1 because 1 based
            resp=app.nnp.write(7,'1F57',107,uint8(value),'uint8'); %6B
            %success=(resp==0);
            if resp==0
                message=itemString;
                if value>0
                    app.ToggleButton_2.Enable='on';
                    app.DnButton.Enable='on';
                    app.MidButton.Enable='on';
                    app.UpButton.Enable='on';
                else
                    app.ToggleButton_2.Enable='off';
                    app.DnButton.Enable='off';
                    app.MidButton.Enable='off';
                    app.UpButton.Enable='off';
                end
            else
                message='Set Toggle Option failed';
            end
            app.TopMessage(message);
        end

        % Button pushed function: activeFGButton
        function activeFGButtonPushed(app, event)
            app.getActiveFG([1:6]); % need way to configure number of nodes here    
        end

        % Value changed function: DisplaySwitch
        function DisplaySwitchValueChanged(app, event)
            value = app.DisplaySwitch.Value;
            if strcmp(value,'Advanced')
              app.DiagnosticPanel.Visible='on';  
%               app.PMVersionButton.Visible='on';  
%               app.NetOnButton.Visible='on';  
%               app.NetOffButton.Visible='on';  
%               app.ScriptStatusButton.Visible='on';  
%               app.activeFGButton.Visible='on';  
%               app.SystemPanel.Visible='on';  
%               app.ScriptsPanel.Visible='on';  
%               app.CfgButton.Visible='on';  
              app.AuxArmPanel.Visible='on';  
              app.HandTogglePanel.Visible='on';  
%               app.MESTestDataButton.Visible='on';  
              
              
            else
              app.DiagnosticPanel.Visible='off'; 
%               app.PMVersionButton.Visible='off';  
%               app.NetOnButton.Visible='off';  
%               app.NetOffButton.Visible='off';  
%               app.ScriptStatusButton.Visible='off';  
%               app.activeFGButton.Visible='off';  
%               app.SystemPanel.Visible='off';  
%               app.ScriptsPanel.Visible='off';  
%               app.CfgButton.Visible='off';  
              app.AuxArmPanel.Visible='off';  
              app.HandTogglePanel.Visible='off';  
%               app.MESTestDataButton.Visible='off';        
            end
        end

        % Button pushed function: MESTestDataButton
        function MESTestDataButtonPushed(app, event)
            mesTestData(app.nnp,app.phoneAppEmulation);
        end

        % Button pushed function: ReadCurrentAuxButton
        function ReadCurrentAuxButtonPushed(app, event)
            % Read the parameter 1F57.6E (110) from PM and set the dropdown box accordingly.
            % 0=ramp, 1=Accelerometer  (plan to add ramp-toggle to this list)
            resp = app.nnp.read(7,'1F57',110,1,'uint8');
            if length(resp) ~= 1
                message='Read Aux Method failed';
                app.TopMessage(message);
                return
            else
                message='Read Aux Method Option';
                app.TopMessage(message);                
                app.SetAuxOptionDropDown.Value=resp;
                app.SetAuxOptionDropDown.Enable='on';
                if resp>0
                    app.Aux1UpButton.Enable='off';
                    app.Aux1DownButton.Enable='off';
                    app.Aux1ToggleButton.Enable='off';
                else
                    app.Aux1UpButton.Enable='on';
                    app.Aux1DownButton.Enable='on';
                    app.Aux1ToggleButton.Enable='on';
                end
            end 
        end

        % Value changed function: SetAuxOptionDropDown
        function SetAuxOptionDropDownValueChanged(app, event)
            value = app.SetAuxOptionDropDown.Value;
            
            % When dropdown is changed, send the new value to 1F57.6E (110), but
            % first send 00 to 1F53.13 (19) for down
            if value>0
                resp=app.nnp.write(7,'1F53',19,uint8(2),'uint8');
                if resp==0
                    % may want to include message here
                end
            end
            itemString=app.SetAuxOptionDropDown.Items{value+1}; % add 1 because 1 based
            resp=app.nnp.write(7,'1F57',110,uint8(value),'uint8'); %6E
            %success=(resp==0);
            if resp==0
                message=itemString;
                if value==0 % currently ramp is the first option
                    app.Aux1UpButton.Enable='on';
                    app.Aux1DownButton.Enable='on';
                else
                    app.Aux1UpButton.Enable='off';
                    app.Aux1DownButton.Enable='off';
                end
            else
                message='Set Aux Option failed';
            end
            app.TopMessage(message);            
        end

        % Button pushed function: ToggleButton_2
        function ToggleButton_2Pushed(app, event)
            % send toggle to 1F53.51 (1=toggle)
            resp=app.nnp.write(7,'1F53',81,uint8(1),'uint8');
            if resp==0
                message='Toggle Command';
            else
                message='Toggle Command failed';
            end
            app.TopMessage(message);
        end

        % Button pushed function: DnButton
        function DnButtonPushed(app, event)
            % send toggle to 1F53.51 (2=zero/reset)
            resp=app.nnp.write(7,'1F53',81,uint8(2),'uint8');
            if resp==0
                message='Toggle Down';
            else
                message='Toggle Down failed';
            end
            app.TopMessage(message);
        end

        % Button pushed function: MidButton
        function MidButtonPushed(app, event)
            % send toggle to 1F53.51 (3=mid)
            resp=app.nnp.write(7,'1F53',81,uint8(3),'uint8');
            if resp==0
                message='Toggle Mid';
            else
                message='Toggle Mid failed';
            end
            app.TopMessage(message);
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            % send toggle to 1F53.51 (4=up)
            resp=app.nnp.write(7,'1F53',81,uint8(4),'uint8');
            if resp==0
                message='Toggle Up';
            else
                message='Toggle Up failed';
            end
            app.TopMessage(message);
        end

        % Button pushed function: ShutdownPMButton
        function ShutdownPMButtonPushed(app, event)
            app.ShowdownPM();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.phoneAppEmulation = uifigure('Visible', 'off');
            app.phoneAppEmulation.Name = 'Phone App Functions';
            app.phoneAppEmulation.CloseRequestFcn = createCallbackFcn(app, @phoneAppEmulationCloseRequest, true);
            app.FileMenu = uimenu(app.phoneAppEmulation);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.Text = 'SaveAs';
            app.PhoneAppFunctionsLabel = uilabel(app.phoneAppEmulation);
            app.PhoneAppFunctionsLabel.Text = 'Phone App Functions';
            app.HandPanel = uipanel(app.phoneAppEmulation);
            app.HandPanel.Title = 'Hand';
            app.HandButton1 = uibutton(app.HandPanel, 'push');
            app.HandButton1.ButtonPushedFcn = createCallbackFcn(app, @HandButton1Pushed, true);
            app.HandButton1.Text = 'Hand 1';
            app.HandButton2 = uibutton(app.HandPanel, 'push');
            app.HandButton2.ButtonPushedFcn = createCallbackFcn(app, @HandButton2Pushed, true);
            app.HandButton2.Text = 'Hand 2';
            app.HandButton3 = uibutton(app.HandPanel, 'push');
            app.HandButton3.ButtonPushedFcn = createCallbackFcn(app, @HandButton3Pushed, true);
            app.HandButton3.Text = 'Hand 3';
            app.HandButton4 = uibutton(app.HandPanel, 'push');
            app.HandButton4.ButtonPushedFcn = createCallbackFcn(app, @HandButton4Pushed, true);
            app.HandButton4.Text = 'Hand 4';
            app.HandButton5 = uibutton(app.HandPanel, 'push');
            app.HandButton5.ButtonPushedFcn = createCallbackFcn(app, @HandButton5Pushed, true);
            app.HandButton5.Text = 'Hand 5';
            app.ExercisePanel = uipanel(app.phoneAppEmulation);
            app.ExercisePanel.Title = 'Exercise';
            app.ExerciseButton1 = uibutton(app.ExercisePanel, 'push');
            app.ExerciseButton1.ButtonPushedFcn = createCallbackFcn(app, @ExerciseButton1Pushed, true);
            app.ExerciseButton1.Text = 'Exercise 1';
            app.Exercise2Button2 = uibutton(app.ExercisePanel, 'push');
            app.Exercise2Button2.ButtonPushedFcn = createCallbackFcn(app, @Exercise2Button2Pushed, true);
            app.Exercise2Button2.Text = 'Exercise 2';
            app.Exercise2Button3 = uibutton(app.ExercisePanel, 'push');
            app.Exercise2Button3.ButtonPushedFcn = createCallbackFcn(app, @Exercise2Button3Pushed, true);
            app.Exercise2Button3.Text = 'Exercise 3';
            app.Exercise2Button4 = uibutton(app.ExercisePanel, 'push');
            app.Exercise2Button4.ButtonPushedFcn = createCallbackFcn(app, @Exercise2Button4Pushed, true);
            app.Exercise2Button4.Text = 'Exercise 4';
            app.Exercise2Button5 = uibutton(app.ExercisePanel, 'push');
            app.Exercise2Button5.ButtonPushedFcn = createCallbackFcn(app, @Exercise2Button5Pushed, true);
            app.Exercise2Button5.Enable = 'off';
            app.Exercise2Button5.Visible = 'off';
            app.Exercise2Button5.Text = 'Exercise 5';
            app.TrunkPanel = uipanel(app.phoneAppEmulation);
            app.TrunkPanel.Title = 'Trunk';
            app.TrunkButton1 = uibutton(app.TrunkPanel, 'push');
            app.TrunkButton1.ButtonPushedFcn = createCallbackFcn(app, @TrunkButton1Pushed, true);
            app.TrunkButton1.Text = 'Trunk 1';
            app.TrunkButton2 = uibutton(app.TrunkPanel, 'push');
            app.TrunkButton2.ButtonPushedFcn = createCallbackFcn(app, @TrunkButton2Pushed, true);
            app.TrunkButton2.Text = 'Trunk 2';
            app.TrunkButton3 = uibutton(app.TrunkPanel, 'push');
            app.TrunkButton3.ButtonPushedFcn = createCallbackFcn(app, @TrunkButton3Pushed, true);
            app.TrunkButton3.Text = 'Trunk 3';
            app.TrunkButton4 = uibutton(app.TrunkPanel, 'push');
            app.TrunkButton4.ButtonPushedFcn = createCallbackFcn(app, @TrunkButton4Pushed, true);
            app.TrunkButton4.Text = 'Trunk 4';
            app.TrunkButton5 = uibutton(app.TrunkPanel, 'push');
            app.TrunkButton5.ButtonPushedFcn = createCallbackFcn(app, @TrunkButton5Pushed, true);
            app.TrunkButton5.Text = {'Stand'; 'Transfer'};
            app.StopPanel = uipanel(app.phoneAppEmulation);
            app.StopPanel.Title = 'Stop';
            app.StopHandButton = uibutton(app.StopPanel, 'push');
            app.StopHandButton.ButtonPushedFcn = createCallbackFcn(app, @StopHandButtonPushed, true);
            app.StopHandButton.Text = {'Stop'; 'Hand'};
            app.StopBothButton = uibutton(app.StopPanel, 'push');
            app.StopBothButton.ButtonPushedFcn = createCallbackFcn(app, @StopBothButtonPushed, true);
            app.StopBothButton.Text = {'Stop'; 'Both'};
            app.StopTrunkButton = uibutton(app.StopPanel, 'push');
            app.StopTrunkButton.ButtonPushedFcn = createCallbackFcn(app, @StopTrunkButtonPushed, true);
            app.StopTrunkButton.Text = {'Stop'; 'Trunk'};
            app.CoughPanel = uipanel(app.phoneAppEmulation);
            app.CoughPanel.Title = 'Cough';
            app.EnableCoughButton = uibutton(app.CoughPanel, 'push');
            app.EnableCoughButton.ButtonPushedFcn = createCallbackFcn(app, @EnableCoughButtonPushed, true);
            app.EnableCoughButton.Tooltip = {'To Disable '; 'press Stop Both'};
            app.EnableCoughButton.Text = {'Enable'; 'Cough'};
            app.CoughButton = uibutton(app.CoughPanel, 'push');
            app.CoughButton.ButtonPushedFcn = createCallbackFcn(app, @CoughButtonPushed, true);
            app.CoughButton.Text = 'Cough';
            app.StatusPanel = uipanel(app.phoneAppEmulation);
            app.StatusPanel.Title = 'Status';
            app.GetStatusButton = uibutton(app.StatusPanel, 'push');
            app.GetStatusButton.ButtonPushedFcn = createCallbackFcn(app, @GetStatusButtonPushed, true);
            app.GetStatusButton.Text = 'Get Status';
            app.ScanButton = uibutton(app.StatusPanel, 'state');
            app.ScanButton.ValueChangedFcn = createCallbackFcn(app, @ScanButtonValueChanged, true);
            app.ScanButton.Text = 'Scan';
            app.ScanLED = uilamp(app.StatusPanel);
            app.scanIntLabel = uilabel(app.StatusPanel);
            app.scanIntLabel.Text = 'scan Int';
            app.ScanIntervalEditField = uieditfield(app.StatusPanel, 'numeric');
            app.ScanIntervalEditField.ValueDisplayFormat = '%.3f';
            app.ScanIntervalEditField.Value = 1;
            app.SystemPanel = uipanel(app.phoneAppEmulation);
            app.SystemPanel.Title = 'System';
            app.ModeEditFieldLabel = uilabel(app.SystemPanel);
            app.ModeEditFieldLabel.Text = 'Mode';
            app.ModeEditField = uieditfield(app.SystemPanel, 'numeric');
            app.NetworkLampLabel = uilabel(app.SystemPanel);
            app.NetworkLampLabel.Text = 'Network';
            app.NetworkLamp = uilamp(app.SystemPanel);
            app.PMTempEditFieldLabel = uilabel(app.SystemPanel);
            app.PMTempEditFieldLabel.Text = 'PM Temp';
            app.PMTempEditField = uieditfield(app.SystemPanel, 'numeric');
            app.PMTempEditField.ValueDisplayFormat = '%3.1f';
            app.BatteriesEditFieldLabel = uilabel(app.SystemPanel);
            app.BatteriesEditFieldLabel.Text = 'Batteries';
            app.BatteriesEditField = uieditfield(app.SystemPanel, 'numeric');
            app.BatteriesEditField.ValueDisplayFormat = '%3.1f';
            app.RSSIEditFieldLabel = uilabel(app.SystemPanel);
            app.RSSIEditFieldLabel.Text = 'RSSI';
            app.RSSIEditField = uieditfield(app.SystemPanel, 'numeric');
            app.RSSIEditField.ValueDisplayFormat = '%3.1f';
            app.MESPanel = uipanel(app.phoneAppEmulation);
            app.MESPanel.Title = 'MES';
            app.WFL_1 = uieditfield(app.MESPanel, 'numeric');
            app.WFL_2 = uieditfield(app.MESPanel, 'numeric');
            app.ASF_1 = uieditfield(app.MESPanel, 'numeric');
            app.ASF_2 = uieditfield(app.MESPanel, 'numeric');
            app.WFLASFLabel = uilabel(app.MESPanel);
            app.WFLASFLabel.Text = 'WFL       ASF';
            app.Label = uilabel(app.MESPanel);
            app.Label.Text = {'1'; '2'};
            app.LogicPanel = uipanel(app.phoneAppEmulation);
            app.LogicPanel.Title = 'Logic';
            app.LengthEditFieldLabel = uilabel(app.LogicPanel);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.LogicPanel, 'numeric');
            app.TwitchEditFieldLabel = uilabel(app.LogicPanel);
            app.TwitchEditFieldLabel.Text = 'Twitch';
            app.TwitchEditField = uieditfield(app.LogicPanel, 'numeric');
            app.LockButton = uibutton(app.LogicPanel, 'push');
            app.LockButton.ButtonPushedFcn = createCallbackFcn(app, @LockButtonPushed, true);
            app.LockButton.Tooltip = {'Lock or unlock hand.  Emulates the lock or unlock command.'};
            app.LockButton.Text = 'Lock';
            app.CommandPanel = uipanel(app.phoneAppEmulation);
            app.CommandPanel.Title = 'Command';
            app.HandPattern = uieditfield(app.CommandPanel, 'numeric');
            app.Aux1Pattern = uieditfield(app.CommandPanel, 'numeric');
            app.TrunkPattern = uieditfield(app.CommandPanel, 'numeric');
            app.Aux4Pattern = uieditfield(app.CommandPanel, 'numeric');
            app.Aux4Pattern.Tooltip = {'Currently Aux4 is cough pattern, but uses trunk command level because it is part of trunk script.'};
            app.Aux4Command = uieditfield(app.CommandPanel, 'numeric');
            app.TrunkCommand = uieditfield(app.CommandPanel, 'numeric');
            app.Aux1Command = uieditfield(app.CommandPanel, 'numeric');
            app.Aux1Command.Tooltip = {'Currently Aux1 is elbow extension controlled by Accelerometers or Aux toggle if configured'};
            app.HandCommand = uieditfield(app.CommandPanel, 'numeric');
            app.PatternLabel = uilabel(app.CommandPanel);
            app.PatternLabel.Text = 'Pattern';
            app.CommandLabel = uilabel(app.CommandPanel);
            app.CommandLabel.Text = 'Command';
            app.HandAux1Label = uilabel(app.CommandPanel);
            app.HandAux1Label.Text = 'Hand   Aux1';
            app.TrunkAux4Label = uilabel(app.CommandPanel);
            app.TrunkAux4Label.Text = 'Trunk  Aux4';
            app.ExercisePanel_2 = uipanel(app.phoneAppEmulation);
            app.ExercisePanel_2.Title = 'Exercise';
            app.CycleEditFieldLabel = uilabel(app.ExercisePanel_2);
            app.CycleEditFieldLabel.Text = 'Cycle';
            app.CycleEditField = uieditfield(app.ExercisePanel_2, 'numeric');
            app.SetEditFieldLabel = uilabel(app.ExercisePanel_2);
            app.SetEditFieldLabel.Text = 'Set';
            app.SetEditField = uieditfield(app.ExercisePanel_2, 'numeric');
            app.StateEditFieldLabel = uilabel(app.ExercisePanel_2);
            app.StateEditFieldLabel.Text = 'State';
            app.StateEditField = uieditfield(app.ExercisePanel_2, 'numeric');
            app.ExPattern_1 = uieditfield(app.ExercisePanel_2, 'numeric');
            app.ExPattern_1.Tooltip = {'Exercise pattern.  Uses Hand and trunk command levels which all change together in exercise'};
            app.ExPattern_2 = uieditfield(app.ExercisePanel_2, 'numeric');
            app.RegtEditField = uieditfield(app.ExercisePanel_2, 'numeric');
            app.RegEditFieldLabel = uilabel(app.ExercisePanel_2);
            app.RegEditFieldLabel.Text = 'Reg';
            app.AuxArmPanel = uipanel(app.phoneAppEmulation);
            app.AuxArmPanel.Title = 'Aux Arm';
            app.AuxOnOffButton = uibutton(app.AuxArmPanel, 'push');
            app.AuxOnOffButton.ButtonPushedFcn = createCallbackFcn(app, @AuxOnOffButtonPushed, true);
            app.AuxOnOffButton.Tooltip = {'Currently aux is triceps.  Can be configured as button ramp or Acc controlled'};
            app.AuxOnOffButton.Text = {'Aux'; 'On/Off'};
            app.Aux1DownButton = uibutton(app.AuxArmPanel, 'push');
            app.Aux1DownButton.ButtonPushedFcn = createCallbackFcn(app, @Aux1DownButtonPushed, true);
            app.Aux1DownButton.Tooltip = {'Down if in ramp mode'};
            app.Aux1DownButton.Text = 'Down';
            app.Aux1UpButton = uibutton(app.AuxArmPanel, 'push');
            app.Aux1UpButton.ButtonPushedFcn = createCallbackFcn(app, @Aux1UpButtonPushed, true);
            app.Aux1UpButton.Tooltip = {'Up if in ramp mode'};
            app.Aux1UpButton.Text = 'Up';
            app.ReadCurrentAuxButton = uibutton(app.AuxArmPanel, 'push');
            app.ReadCurrentAuxButton.ButtonPushedFcn = createCallbackFcn(app, @ReadCurrentAuxButtonPushed, true);
            app.ReadCurrentAuxButton.Text = 'Read Current';
            app.SetAuxOptionLabel = uilabel(app.AuxArmPanel);
            app.SetAuxOptionLabel.Enable = 'off';
            app.SetAuxOptionLabel.Text = ' SetAuxOption';
            app.SetAuxOptionDropDown = uidropdown(app.AuxArmPanel);
            app.SetAuxOptionDropDown.Items = {'Ramp', 'Acc'};
            app.SetAuxOptionDropDown.ValueChangedFcn = createCallbackFcn(app, @SetAuxOptionDropDownValueChanged, true);
            app.SetAuxOptionDropDown.Enable = 'off';
            app.SetAuxOptionDropDown.Value = 'Ramp';
            app.Aux1ToggleButton = uibutton(app.AuxArmPanel, 'push');
            app.Aux1ToggleButton.ButtonPushedFcn = createCallbackFcn(app, @Aux1ToggleButtonPushed, true);
            app.Aux1ToggleButton.Tooltip = {'Down if in ramp mode'};
            app.Aux1ToggleButton.Text = 'Toggle';
            app.TrunkPanel_2 = uipanel(app.phoneAppEmulation);
            app.TrunkPanel_2.Title = 'Trunk';
            app.MaxLevelLabel = uilabel(app.TrunkPanel_2);
            app.MaxLevelLabel.Text = 'Max Level';
            app.MaxLevelEditField = uieditfield(app.TrunkPanel_2, 'numeric');
            app.MaxLevelEditField.Tooltip = {'Maximum functional trunk level.  Up to 5 levels can be contigured'};
            app.MaxCmdEditFieldLabel = uilabel(app.TrunkPanel_2);
            app.MaxCmdEditFieldLabel.Text = 'Max Cmd';
            app.MaxCmdEditField = uieditfield(app.TrunkPanel_2, 'numeric');
            app.MaxCmdEditField.Tooltip = {'Maximum command at maximum trunk level'};
            app.MaxDownButton = uibutton(app.TrunkPanel_2, 'push');
            app.MaxDownButton.ButtonPushedFcn = createCallbackFcn(app, @MaxDownButtonPushed, true);
            app.MaxDownButton.Text = '-';
            app.MaxUpButton = uibutton(app.TrunkPanel_2, 'push');
            app.MaxUpButton.ButtonPushedFcn = createCallbackFcn(app, @MaxUpButtonPushed, true);
            app.MaxUpButton.Text = '+';
            app.MaxDefaultButton = uibutton(app.TrunkPanel_2, 'push');
            app.MaxDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @MaxDefaultButtonPushed, true);
            app.MaxDefaultButton.Text = 'def';
            app.HandTogglePanel = uipanel(app.phoneAppEmulation);
            app.HandTogglePanel.Title = 'HandToggle';
            app.SetToggleOptionDropDownLabel = uilabel(app.HandTogglePanel);
            app.SetToggleOptionDropDownLabel.Enable = 'off';
            app.SetToggleOptionDropDownLabel.Text = ' SetToggle Option';
            app.SetToggleOptionDropDown = uidropdown(app.HandTogglePanel);
            app.SetToggleOptionDropDown.Items = {'none', 'Button', 'Button/Twitch', 'Button/Twitch/Doublet'};
            app.SetToggleOptionDropDown.ValueChangedFcn = createCallbackFcn(app, @SetToggleOptionDropDownValueChanged, true);
            app.SetToggleOptionDropDown.Enable = 'off';
            app.SetToggleOptionDropDown.Value = 'none';
            app.ToggleButton_2 = uibutton(app.HandTogglePanel, 'push');
            app.ToggleButton_2.ButtonPushedFcn = createCallbackFcn(app, @ToggleButton_2Pushed, true);
            app.ToggleButton_2.Enable = 'off';
            app.ToggleButton_2.Text = 'Toggle';
            app.ReadCurrentHTButton = uibutton(app.HandTogglePanel, 'push');
            app.ReadCurrentHTButton.ButtonPushedFcn = createCallbackFcn(app, @ReadCurrentHTButtonPushed, true);
            app.ReadCurrentHTButton.Text = 'Read Current';
            app.DnButton = uibutton(app.HandTogglePanel, 'push');
            app.DnButton.ButtonPushedFcn = createCallbackFcn(app, @DnButtonPushed, true);
            app.DnButton.Enable = 'off';
            app.DnButton.Text = 'Dn';
            app.MidButton = uibutton(app.HandTogglePanel, 'push');
            app.MidButton.ButtonPushedFcn = createCallbackFcn(app, @MidButtonPushed, true);
            app.MidButton.Enable = 'off';
            app.MidButton.Text = 'Mid';
            app.UpButton = uibutton(app.HandTogglePanel, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Enable = 'off';
            app.UpButton.Text = 'Up';
            app.DisplayPanel = uipanel(app.phoneAppEmulation);
            app.DisplayPanel.Title = 'Display';
            app.DisplaySwitch = uiswitch(app.DisplayPanel, 'slider');
            app.DisplaySwitch.Items = {'Basic', 'Advanced'};
            app.DisplaySwitch.ValueChangedFcn = createCallbackFcn(app, @DisplaySwitchValueChanged, true);
            app.DisplaySwitch.Value = 'Advanced';
            app.DiagnosticPanel = uipanel(app.phoneAppEmulation);
            app.DiagnosticPanel.Title = 'Diagnostic';
            app.PMVersionButton = uibutton(app.DiagnosticPanel, 'push');
            app.PMVersionButton.ButtonPushedFcn = createCallbackFcn(app, @PMVersionButtonPushed, true);
            app.PMVersionButton.Text = {'PM'; 'Version'};
            app.CfgButton = uibutton(app.DiagnosticPanel, 'push');
            app.CfgButton.Enable = 'off';
            app.CfgButton.Tooltip = {'Configure displays based on PM configuration.  NOT YET Implemented'};
            app.CfgButton.Text = 'Cfg';
            app.NetOnButton = uibutton(app.DiagnosticPanel, 'push');
            app.NetOnButton.ButtonPushedFcn = createCallbackFcn(app, @NetOnButtonPushed, true);
            app.NetOnButton.Text = 'Net On';
            app.NetOffButton = uibutton(app.DiagnosticPanel, 'push');
            app.NetOffButton.ButtonPushedFcn = createCallbackFcn(app, @NetOffButtonPushed, true);
            app.NetOffButton.Text = 'Net Off';
            app.activeFGButton = uibutton(app.DiagnosticPanel, 'push');
            app.activeFGButton.ButtonPushedFcn = createCallbackFcn(app, @activeFGButtonPushed, true);
            app.activeFGButton.Text = 'active FG';
            app.MESTestDataButton = uibutton(app.DiagnosticPanel, 'push');
            app.MESTestDataButton.ButtonPushedFcn = createCallbackFcn(app, @MESTestDataButtonPushed, true);
            app.MESTestDataButton.Text = 'MES Test Data';
            app.ScriptsPanel = uipanel(app.DiagnosticPanel);
            app.ScriptsPanel.Title = 'Scripts';
            app.EnableButton = uibutton(app.ScriptsPanel, 'push');
            app.EnableButton.ButtonPushedFcn = createCallbackFcn(app, @EnableButtonPushed, true);
            app.EnableButton.Text = 'Enable';
            app.DisableButton = uibutton(app.ScriptsPanel, 'push');
            app.DisableButton.ButtonPushedFcn = createCallbackFcn(app, @DisableButtonPushed, true);
            app.DisableButton.Text = 'Disable';
            app.StartupButton = uibutton(app.ScriptsPanel, 'push');
            app.StartupButton.ButtonPushedFcn = createCallbackFcn(app, @StartupButtonPushed, true);
            app.StartupButton.Text = 'Startup';
            app.ScriptStatusButton = uibutton(app.ScriptsPanel, 'push');
            app.ScriptStatusButton.ButtonPushedFcn = createCallbackFcn(app, @ScriptStatusButtonPushed, true);
            app.ScriptStatusButton.Text = 'Status';
            app.MessagesPanel = uipanel(app.phoneAppEmulation);
            app.MessagesPanel.Title = 'Messages';
            app.Messages = uitextarea(app.MessagesPanel);
            app.ClearMessageButton = uibutton(app.MessagesPanel, 'push');
            app.ClearMessageButton.ButtonPushedFcn = createCallbackFcn(app, @ClearMessageButtonPushed, true);
            app.ClearMessageButton.Text = 'clear';
            app.MessagesLabel = uilabel(app.MessagesPanel);
            app.MessagesLabel.Text = 'Messages';
            app.ShutdownPMButton = uibutton(app.phoneAppEmulation, 'push');
            app.ShutdownPMButton.ButtonPushedFcn = createCallbackFcn(app, @ShutdownPMButtonPushed, true);
            app.ShutdownPMButton.Text = 'Shutdown PM';
            % Show the figure after all components are created
            app.phoneAppEmulation.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = phoneAppFunctions(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.phoneAppEmulation)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.phoneAppEmulation)
        end
    end
end