classdef pmRestoreFileDisplay < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ReadRestoreUI                   matlab.ui.Figure
        RMInfoButton                    matlab.ui.control.Button
        ReadingPanel                    matlab.ui.container.Panel
        test2                           matlab.ui.control.NumericEditField
        nodeLabel                       matlab.ui.control.Label
        test1                           matlab.ui.control.NumericEditField
        indLabel                        matlab.ui.control.Label
        ReadingLamp                     matlab.ui.control.Lamp
        ReadingLampLabel                matlab.ui.control.Label
        SendtoBPButton                  matlab.ui.control.Button
        LoadBPSettingsButton            matlab.ui.control.Button
        SaveBPSettingsButton            matlab.ui.control.Button
        SavePMRestoreButton             matlab.ui.control.Button
        LoadPGTimingButton              matlab.ui.control.Button
        SavePGTimingButton              matlab.ui.control.Button
        ReadFromSwitch                  matlab.ui.control.RockerSwitch
        ReadFromSwitchLabel             matlab.ui.control.Label
        GetAllButton                    matlab.ui.control.Button
        SaveAllButton                   matlab.ui.control.Button
        StartupScriptButton             matlab.ui.control.Button
        ScriptsEnableButton             matlab.ui.control.Button
        ScriptsDisableButton            matlab.ui.control.Button
        ReadRemotePDOsButton            matlab.ui.control.Button
        WriteTestPDOsButton             matlab.ui.control.Button
        AddfeatureselecttobottomrowLabel  matlab.ui.control.Label
        PlotBinaryFileButton            matlab.ui.control.Button
        Label_2                         matlab.ui.control.Label
        needsaveasbuttontosaveconfigurationLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        ConfigTab                       matlab.ui.container.Tab
        PMVersionEditField              matlab.ui.control.NumericEditField
        PMVersionEditFieldLabel         matlab.ui.control.Label
        MESNodesLabel                   matlab.ui.control.Label
        StimNodesLabel                  matlab.ui.control.Label
        NodeListBP                      matlab.ui.control.TextArea
        NodeListStim                    matlab.ui.control.TextArea
        BasicTab                        matlab.ui.container.Tab
        MultiplierEditField             matlab.ui.control.NumericEditField
        MultiplierEditFieldLabel        matlab.ui.control.Label
        Thisisjustaplaceholderfordate2004untilIsortoutthenumbersLabel  matlab.ui.control.Label
        TableDateTime                   matlab.ui.control.Table
        DateBatteryNetworkgohereLabel   matlab.ui.control.Label
        RsyncEditField                  matlab.ui.control.NumericEditField
        RsyncEditFieldLabel             matlab.ui.control.Label
        RindicesEditField               matlab.ui.control.NumericEditField
        RindicesEditFieldLabel          matlab.ui.control.Label
        RbytesEditField                 matlab.ui.control.NumericEditField
        RbytesEditFieldLabel            matlab.ui.control.Label
        RPDOPMTab                       matlab.ui.container.Tab
        Label_3                         matlab.ui.control.Label
        x1600Label                      matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        UITableRS                       matlab.ui.control.Table
        x1400Label                      matlab.ui.control.Label
        TPDOPMTab                       matlab.ui.container.Tab
        x1A00Label                      matlab.ui.control.Label
        UITableTM                       matlab.ui.control.Table
        UITableTS                       matlab.ui.control.Table
        x1800Label                      matlab.ui.control.Label
        ScriptsTab                      matlab.ui.container.Tab
        ScriptSortOrderDropDown         matlab.ui.control.DropDown
        ScriptSortOrderDropDownLabel    matlab.ui.control.Label
        AddsomethinglatertochangesortorderLabel  matlab.ui.control.Label
        ScriptTable                     matlab.ui.control.Table
        F51scriptpointers1F56scriptorderLabel  matlab.ui.control.Label
        F57Tab                          matlab.ui.container.Tab
        Label_7                         matlab.ui.control.Label
        ToPMButton                      matlab.ui.control.Button
        Label_6                         matlab.ui.control.Label
        ToWorkspaceButton               matlab.ui.control.Button
        Table1F57                       matlab.ui.control.Table
        UserparametersmaydistributeoverseveraltabsLabel  matlab.ui.control.Label
        SysControlTab                   matlab.ui.container.Tab
        couldaddbuttonstoupdateanyoftheitemsIwouldwanttochangeLabel  matlab.ui.control.Label
        NetworkVoltageEditField         matlab.ui.control.NumericEditField
        NetworkVoltageEditFieldLabel    matlab.ui.control.Label
        UITable2                        matlab.ui.control.Table
        LowPowerStatusEditField         matlab.ui.control.NumericEditField
        LowPowerStatusEditFieldLabel    matlab.ui.control.Label
        MinVsysEditField                matlab.ui.control.NumericEditField
        MinVsysEditFieldLabel           matlab.ui.control.Label
        CommandCurrentEditField         matlab.ui.control.NumericEditField
        CommandCurrentEditFieldLabel    matlab.ui.control.Label
        RequestCurrentEditField         matlab.ui.control.NumericEditField
        RequestCurrentEditFieldLabel    matlab.ui.control.Label
        LoadEditField                   matlab.ui.control.NumericEditField
        LoadEditFieldLabel              matlab.ui.control.Label
        VnetEditField                   matlab.ui.control.NumericEditField
        VnetEditFieldLabel              matlab.ui.control.Label
        VsysEditField                   matlab.ui.control.NumericEditField
        VsysEditFieldLabel              matlab.ui.control.Label
        VrecEditField                   matlab.ui.control.NumericEditField
        VrecEditFieldLabel              matlab.ui.control.Label
        ChargeRunEditField              matlab.ui.control.NumericEditField
        ChargeRunEditFieldLabel         matlab.ui.control.Label
        StepLimitEditField              matlab.ui.control.NumericEditField
        StepLimitEditFieldLabel         matlab.ui.control.Label
        NoChargeLimitEditField          matlab.ui.control.NumericEditField
        NoChargeLimitEditFieldLabel     matlab.ui.control.Label
        ChargingIntervalEditField       matlab.ui.control.NumericEditField
        ChargingIntervalEditFieldLabel  matlab.ui.control.Label
        StepIncrementEditField          matlab.ui.control.NumericEditField
        StepIncrementEditFieldLabel     matlab.ui.control.Label
        PowerControlEditField           matlab.ui.control.NumericEditField
        PowerControlEditFieldLabel      matlab.ui.control.Label
        BatteryControlLabel             matlab.ui.control.Label
        ScriptDelayTimeEditField        matlab.ui.control.NumericEditField
        ScriptDelayTimeEditFieldLabel   matlab.ui.control.Label
        ShutdownScriptPointerEditField  matlab.ui.control.NumericEditField
        ShutdownScriptPointerEditFieldLabel  matlab.ui.control.Label
        StartupScriptPointerEditField   matlab.ui.control.NumericEditField
        StartupScriptPointerEditFieldLabel  matlab.ui.control.Label
        CurrentGroupEditField           matlab.ui.control.NumericEditField
        CurrentGroupEditFieldLabel      matlab.ui.control.Label
        SystemControlEditField          matlab.ui.control.EditField
        SystemControlEditFieldLabel     matlab.ui.control.Label
        SystemcontrollnoteLabel         matlab.ui.control.Label
        RM_PDOsTab                      matlab.ui.container.Tab
        MappingsLabel                   matlab.ui.control.Label
        SettingsLabel                   matlab.ui.control.Label
        TransmitLabel                   matlab.ui.control.Label
        ReceiveLabel                    matlab.ui.control.Label
        NodeSelect                      matlab.ui.control.DropDown
        NodeLabel                       matlab.ui.control.Label
        x1A00Label_5                    matlab.ui.control.Label
        TPDO_RMmap                      matlab.ui.control.Table
        TPDO_RMcfg                      matlab.ui.control.Table
        x1800Label_5                    matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        x1600Label_5                    matlab.ui.control.Label
        RPDO_RMmap                      matlab.ui.control.Table
        RPDO_RMcfg                      matlab.ui.control.Table
        x1400Label_5                    matlab.ui.control.Label
        PGTab                           matlab.ui.container.Tab
        PGsetupTable                    matlab.ui.control.Table
        Label                           matlab.ui.control.Label
        BPTab                           matlab.ui.container.Tab
        DecodeorsetfeaturesinseparatewindowLabel  matlab.ui.control.Label
        DecodeFeaturesButton            matlab.ui.control.Button
        NeedtoupdatethissynctimingLabel  matlab.ui.control.Label
        BPChanCfgTable                  matlab.ui.control.Table
        CurrentGroupEditField_2         matlab.ui.control.EditField
        CurrentGroupEditField_2Label    matlab.ui.control.Label
        synctiming3411ch1config3511ch2config3412ch1featuresLabel  matlab.ui.control.Label
        testEditField                   matlab.ui.control.NumericEditField
        testEditFieldLabel              matlab.ui.control.Label
        GainEditField                   matlab.ui.control.NumericEditField
        GainEditFieldLabel              matlab.ui.control.Label
        SyncIntEditField                matlab.ui.control.NumericEditField
        SyncIntEditFieldLabel           matlab.ui.control.Label
        BPschedule                      matlab.ui.control.Table
        UIAxes                          matlab.ui.control.UIAxes
        RMTab                           matlab.ui.container.Tab
        RMInfoTextArea                  matlab.ui.control.TextArea
        RMInfoTextAreaLabel             matlab.ui.control.Label
        ConfigAPButton                  matlab.ui.control.Button
        ReadBPSettingsButton            matlab.ui.control.Button
        ReadPGTimingButton              matlab.ui.control.Button
        ReadPMRestoreButton             matlab.ui.control.Button
        ReadandRestoreUserDataLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        RestoreFileName='TempRestoreFileName.bin'; % Save this to a temporary file
        RFileID=[]; % File ID for Retore File
        PDOmap={[]};
        PDOset={[]};
        ScriptInfo=zeros(25,5); %create an array of script pointers.  Start with double and see if that  works.
        Scriptptr=zeros(1,4);  %read as status, ID, unused, error
        RowNameInput=struct; % define the empty structure
        Matrix1F57=cell(100,2);%use cell array to allow mix of labels and numbers
        ChargeData=zeros(11,1);
        PlottedFileName='  ';
        File2plot=' ';% when I collect raw data, will pass file name on to plotting function.
        BPnode=9;
        BPchanset=zeros(8,2);
        PGtiming={[]};
        PGtiming2D={[]};
        PMR405=true; % Power module restore file is long verion when PM version greater than 404
        callingFigure=[]; %handle to calling figure
    end
    
    properties (Access = public)
        nnp = []; % Description
        PGnodelist=[];
        RMnumlist=[]; % Numerical list of remote modules
        RMpdonum=[];
        RMnodelength=7;
        RMPDOs=struct('RPDOcfg',{[]},'RPDOmap',{[]},'TPDOcfg',{[]},'TPDOmap',{[]});  % structure for remote modules data
    end
    
    methods (Access = private)
        
        function pdosettings = getPDOsettings(app, rfileid,firstbyte,n)
            % The plan here is to create a single funciton to fill the cell array and reuse it in other locations
            fseek(rfileid,firstbyte,'bof'); %set cursor to correct location in the file
            pdosettings={[]};
            %           Since visually, these make no sense as doubles, need to convert to hex
            for i=1:n
                pdosettings{1,i}=dec2hex(fread(rfileid,1,'uint32'),8);
                pdosettings{2,i}=dec2hex(fread(rfileid,1,'uint8'),2);
                pdosettings{3,i}=dec2hex(fread(rfileid,1,'uint16'),4);
                pdosettings{4,i}=dec2hex(fread(rfileid,1,'uint8'),2);
                pdosettings{5,i}=dec2hex(fread(rfileid,1,'uint16'),4);
            end
        end
        
        function pdomaps = getPDOmaps(app,rfileid,firstbyte,n)
            pdobytes=8*n; % 8 bytes times number of pdos
            fseek(rfileid,firstbyte,'bof'); %set cursor to correct location in the file
            pdomaps=reshape(cellstr(dec2hex(fread(rfileid,pdobytes,'uint32'),4)),8,n);  %256 bytes starting at n
        end
        
        function pdosettings = ODPDOsettings(app,firstindex,node,n)
            
            %             The plan here is to create a single funciton to fill the cell array and reuse it in other locations
            %             The index to read will be first index plus an increment. Since Matlab indexes by 1, it will be fi+i-1
            pdosettings={[]};
            %indexOD=[]; %plan to for firstindex to be hex, ie '1400'
            %           Since visually, these make no sense as doubles, need to convert to hex
            for i=1:n
                indexOD=dec2hex((hex2dec(firstindex)+i-1),4); %since hex math is not allowed, need to convert twice
                pdosettings{1,i}=dec2hex(app.nnp.read(node,indexOD,1,'uint32'),8);
                pdosettings{2,i}=dec2hex(app.nnp.read(node,indexOD,2,'uint8'),2);
                pdosettings{3,i}=dec2hex(app.nnp.read(node,indexOD,3,'uint16'),4);
                pdosettings{4,i}=dec2hex(app.nnp.read(node,indexOD,4,'uint8'),2);
                pdosettings{5,i}=dec2hex(app.nnp.read(node,indexOD,5,'uint16'),4);
                pause(.3);
            end
        end
        
        
        function pdomaps = ODPDOmaps(app,firstindex,node,n)
            pdomaps={[]};
            %             indexOD=firstindex;  %plan to for firstindex to be hex, ie '1600'
            %           Since visually, these make no sense as doubles, need to convert to hex
            for i=1:n
                indexOD=dec2hex((hex2dec(firstindex)+i-1),4); %since hex math is not allowed, need to convert twice
                for j=1:8
                    pdomaps{j,i}=dec2hex(app.nnp.read(node,indexOD,j,'uint32'),8);
                end
                pause(.3);
            end
        end
    end
    
    methods (Access = public)
        
        function pgtiming = getPGtiming(app,n)
            pgtiming={[]};
            pgtiming{1}=num2str(n);
            for i=1:6
            pgtiming{i+1}=num2str(app.nnp.read(n,'2800',i,'uint8'));
            pause(.1); %add pause to allow CAN traffic to get through
            end
            pgtiming{8}=num2str(app.nnp.read(n,'3210',3,'uint16')/60); %stimVOS
            pgtiming{9}=num2str(app.nnp.read(n,'3210',4,'uint16')/60);  %minVOS
            pgtiming=pgtiming';
        end
        
        function UpdateNodeDDlist(app)
            a=str2num(cell2mat(app.NodeListStim.Value));
            b=str2num(cell2mat(app.NodeListBP.Value));
            c=[a b]; % list of RM node numbers
            d=[(a*0+1) b*0+4]; % number of pdos in listed in each RM
            app.RMnumlist=c;
            app.RMpdonum=d;
            app.PGnodelist=a;
            %             app.NodeSelect.ItemsData=app.RMnumlist;
            app.NodeSelect.ItemsData=[1:size(app.RMnumlist,2)]; % allows index of dropdown, then can use n=app.RMnumlist(i)
            app.NodeSelect.Items=string(app.RMnumlist);
            app.RMnodelength=size(app.RMnumlist,2);
        end
        
        
        function defaultPGcellarray(app,n)
            %fill the default cell array with proper data, based upon the number of nodes
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin<2
                NNP=[];
            else
                NNP=varargin{1};
            end
            if isempty(NNP)
                app.nnp = NNPAPI();
                if isempty(app.nnp.port)
                    msgbox('No port chosen for NNP Access Point')
                    delete(app.nnp)
%                     return; %comment out because I want this to continue
%                     even if no nnp
                    
                end
            else
                app.nnp = NNP;
            end
            if nargin>2
                app.callingFigure=varargin{2};
            end

            
            app.UITable.Data=zeros(8);
            app.UITable.RowName='numbered';
            app.UITableRS.RowName={'ID','type','inhibit','compat','timer'};
            app.UITableTM.Data=zeros(8);
            app.UITableTM.RowName='numbered';
            app.UITableTS.RowName={'ID','type','inhibit','compat','timer'};
            app.ScriptTable.RowName='numbered';
            app.RowNameInput=load('RowNames5753.mat'); %this should read the variables into a structure

            app.BPschedule.RowName='numbered';
            app.BPChanCfgTable.RowName={'Gain','Depth','ThreshZX', 'ThreshSSC', 'MAVgain', 'MVgain', 'WFLgain','Features'};
            app.PGsetupTable.RowName={'Node','Stim Timing','Sync Interval','SyncPush','AutoSyncTime','MaxAutoSyncCount','MinDischargeTime','StimVOS','minVOS'};
            UpdateNodeDDlist(app);

        end

        % Button pushed function: ConfigAPButton
        function ConfigAPButtonPushed(app, event)
            radioconfig(app.nnp);
        end

        % Button pushed function: ReadPMRestoreButton
        function ReadPMRestoreButtonPushed(app, event)
            % will need to first create a filename, since this is not configured to read directly to a variable
            %read from PM or from file
            value = app.ReadFromSwitch.Value; 
            switch value
                case 'On' % from File
                    % request PM version number for file, or read length
                    [fn,pn]=uigetfile('*.bin');
                    fullname=fullfile(pn,fn);
                    app.RFileID=fopen(fullname); % get fileID
                    figure(app.ReadRestoreUI);%bring back focus
                case 'Off'
                     resp=double(app.nnp.read(7,'1018',3,'uint32')); % get the revision number.  Anything after 404 has 1F57=150bytes
                     if ~isempty(resp)
                         app.PMVersionEditField.Value=resp;
                     end
                    app.nnp.readFile('odrestore', 0, 1000, 'bin', app.RestoreFileName);
                    app.RFileID=fopen(app.RestoreFileName); % get fileID
            end
        % Read number of bytes in restore file
            fseek(app.RFileID,0,'bof'); %set cursor to correct location in the file
            app.RbytesEditField.Value=fread(app.RFileID,1,'uint16');
            % < 405 restore data 984 bytes
            % >= 405 restore data 950 bytes
                        if app.RbytesEditField.Value<984  % size for PM version up through 404
                            app.PMR405=true;
                        else
                            app.PMR405=false;
                        end
            % Read number of indices
            fseek(app.RFileID,2,'bof'); %set cursor to correct location in the file
            app.RindicesEditField.Value=fread(app.RFileID,1,'uint8');
            
            % Read Sync period
            fseek(app.RFileID,3,'bof'); %set cursor to correct location in the file
            app.RsyncEditField.Value=fread(app.RFileID,1,'uint32');
            % Read Receive PDO Settings
            % 1400-1407, 10 bytes, 4,1,2,1,2   RPDO Settings
            % 1600-1607, 32 bytes, 4 bytes each RPDO Mappings
            app.UITableRS.Data=getPDOsettings(app,app.RFileID,7,8);  %trying helper funciton
            app.UITable.Data=getPDOmaps(app,app.RFileID,87,8);
            
            % Read Transmit PDO Settings
            % 1800-1807, 10 bytes, 4,1,2,1,2   TPDO Settings
            % 1A00-1A07, 32 bytes, 4 bytes each TPDO Mappings
            if app.PMR405
                app.UITableTS.Data=getPDOsettings(app,app.RFileID,343,6);
                app.UITableTM.Data=getPDOmaps(app,app.RFileID,403,6);
            else
                app.UITableTS.Data=getPDOsettings(app,app.RFileID,343,8);
                app.UITableTM.Data=getPDOmaps(app,app.RFileID,423,8);
            end
            %  script pointers 
            
            if app.PMR405
                fseek(app.RFileID,595,'bof'); %set cursor to correct location in the file
            else
                fseek(app.RFileID,679,'bof'); %set cursor to correct location in the file
            end
            app.ScriptInfo(:,1)=[1:25]; %first row is script pointer

            for i=1:25
                % i=pointer, bytes: 1=status,2=ID, 3=unused, 4=error code, last bit of 3 is run once completed
                % order I want is i,2,1,4
                app.Scriptptr=fread(app.RFileID,4,'uint8');  %read in first four columns of data
                app.ScriptInfo(i,2:4)=app.Scriptptr([2 1 4]); % this will make it harder when writing back, but easier to read
            end
            % script order
            if app.PMR405
                fseek(app.RFileID,695,'bof'); %set cursor to correct location in the file
                
            else
                fseek(app.RFileID,779,'bof'); %set cursor to correct location in the file
            end
            % I need to read in the script order, which is the script pointers in the order in which they will execute, then sort them
            % into a list arranged by script order to match the table order.
            % One way to do that is to use the data point read as the index to the table and i as the value.
            for i=1:25
                 n=fread(app.RFileID,1,'uint8');
                 if n>0                    % make sure not out of range
                     if n<26
                        app.ScriptInfo(n,5)=uint8(i);
                     end
                 end
%                 app.ScriptInfo(i,5)=fread(app.RFileID,1,'uint8');
            end
            app.ScriptTable.Data=app.ScriptInfo; %write script info to table
            %Later, when I add a sort order button I can figure out how to sort the ScriptInfo array before copying to table
            % syntax sortcol=2; b=sortrows(a,sortcol);
            
            %Read 1F57 Index
            % Use cell array so that I can have both hex conversion and labels
            if app.PMR405
                % will need two versions of rownames
                app.Matrix1F57=cell(150,2);%use cell array to allow mix of labels and numbers
                app.Matrix1F57(1:150,1)=app.RowNameInput.RowNames1F57; %these were read from .mat file at startup
                fseek(app.RFileID,720,'bof'); %set cursor to correct location in the file
                app.Matrix1F57(:,2)=num2cell(fread(app.RFileID,150,'uint8'));
                normaltable=app.Matrix1F57(1:150,1:2); % copy into normal table
                subbytes=34;
            else
                app.Matrix1F57=cell(100,2);%use cell array to allow mix of labels and numbers
                app.Matrix1F57(:,1)=app.RowNameInput.RowNames1F57(1:100); %these were read from .mat file at startup
                fseek(app.RFileID,804,'bof'); %set cursor to correct location in the file
                app.Matrix1F57(:,2)=num2cell(fread(app.RFileID,100,'uint8'));
                normaltable=app.Matrix1F57(1:100,1:2); % copy into normal table
                
                 subbytes=0;
            end
            app.Table1F57.Data=cell2table(normaltable); % copy into table into uitable
            % Read System Contol 2001
            fseek(app.RFileID,904-subbytes,'bof'); %set cursor to correct location in the file
            app.SystemControlEditField.Value=dec2hex(fread(app.RFileID,1,'uint32'),8);
            app.CurrentGroupEditField.Value=fread(app.RFileID,1,'uint8');
            app.StartupScriptPointerEditField.Value=fread(app.RFileID,1,'uint8');
            app.ShutdownScriptPointerEditField.Value=fread(app.RFileID,1,'uint8');
            app.ScriptDelayTimeEditField.Value=fread(app.RFileID,1,'uint32');
            
            %Read Date/Time Index
            %For now this is a placeholder until I look up the breakdown
            fseek(app.RFileID,915-subbytes,'bof'); %set cursor to correct location in the file
            app.TableDateTime.Data=fread(app.RFileID,6,'uint32');
            app.MultiplierEditField.Value=fread(app.RFileID,1,'uint8');
            
            %Read Battery Control 3000
            %             uint8, PowerControl
            %             uint8, BatteryChargingStepIncrement
            %             uint16, BatteryChargingInterval
            %             uint16, ChargeNFLinkNoChargeLimit
            %             uint16, ChargeNFLinkNoChargeStepLimit
            % Next five are read only
            %             boolean, BatteryChargeRun
            %             uint16, BatteryControl_VREC
            %             uint16, BatteryControl_VSYS
            %             uint16, BatteryControl_VNET
            %             uint16, BatteryControl_LOAD
            %
            %             uint8,  RequestedChargingCurrent
            % Next two are read only
            %             uint8,  CommandedChargingCurrent
            %             uint8x22, ChargingData[22] - since this is read only, can leave as simple array
            %
            %             uint16, MinVSYS
            %             uint8, LowPowerStatus
            fseek(app.RFileID,940-subbytes,'bof'); %set cursor to correct location in the file
            app.PowerControlEditField.Value=fread(app.RFileID,1,'uint8');
            app.StepIncrementEditField.Value=fread(app.RFileID,1,'uint8');
            app.ChargingIntervalEditField.Value=fread(app.RFileID,1,'uint16');
            app.NoChargeLimitEditField.Value=fread(app.RFileID,1,'uint16');
            app.StepLimitEditField.Value=fread(app.RFileID,1,'uint16');
            app.ChargeRunEditField.Value=fread(app.RFileID,1,'uint8');
            app.VrecEditField.Value=fread(app.RFileID,1,'uint16');
            app.VsysEditField.Value=fread(app.RFileID,1,'uint16');
            app.VnetEditField.Value=fread(app.RFileID,1,'uint16');
            app.LoadEditField.Value=fread(app.RFileID,1,'uint16');
            app.RequestCurrentEditField.Value=fread(app.RFileID,1,'uint8');
            app.CommandCurrentEditField.Value=fread(app.RFileID,1,'uint8');
            app.ChargeData(1:7)=fread(app.RFileID,7,'uint16');%first 6 elements are u16
            app.ChargeData(8:10)=fread(app.RFileID,3,'int16');%next 3 elements are int16
            app.ChargeData(11)=fread(app.RFileID,1,'int8');%last is int8
            app.UITable2.Data=app.ChargeData;
            app.MinVsysEditField.Value=fread(app.RFileID,1,'uint16');
            app.LowPowerStatusEditField.Value=fread(app.RFileID,1,'uint8');
            
            % Read Network Voltage
            fseek(app.RFileID,983-subbytes,'bof'); %set cursor to correct location in the file
            app.NetworkVoltageEditField.Value=fread(app.RFileID,1,'uint8');
        end

        % Value changed function: ScriptSortOrderDropDown
        function ScriptSortOrderDropDownValueChanged(app, event)
            sortcol = str2double(app.ScriptSortOrderDropDown.Value);
            app.ScriptTable.Data=sortrows(app.ScriptInfo,sortcol); %write script info to table
        end

        % Button pushed function: PlotBinaryFileButton
        function PlotBinaryFileButtonPushed(app, event)
            app.PlottedFileName=emgPlotRawBinary();
        end

        % Button pushed function: ReadBPSettingsButton
        function ReadBPSettingsButtonPushed(app, event)
            % When button is pressed I need turn on the network,  to read and distribute the following:
            app.ReadingLamp.Color='g'; %turn green while reading from RM
            app.ReadingLamp.Enable='on'; %turn lamp on at start
            app.nnp.networkOn();
            pause(1); % wait for network to start
            % app.testEditField.Value=str2num(app.NodeListBP.Value{1});
            app.BPnode=str2num(app.NodeListBP.Value{1});
            % array=str2num(cell2mat(app.NodeListBP.Value))% this would produce a numerical array
            % 1400-1403 receive PDO configuration, 10 bytes per index
%             app.RPDOBPcfg.Data = ODPDOsettings(app,'1400',app.BPnode,4);
%             %
%             % 1600-1603 receive PDO mapping, 32 bytes
%             app.RPDOBPmap.Data=ODPDOmaps(app,'1600',app.BPnode,4);  %function pdomaps = ODPDOmaps(app,firstindex,n)
%             % pdomaps = getPDOmaps(app,rfileid,firstbyte,n)
%             % 1800-1803 TPDO configuration, 10 bytes per index
%             app.TPDOBPcfg.Data = ODPDOsettings(app,'1800',app.BPnode,4);
%             % 1A00-1A03 TPDO mapping, 32 bytes \
%             app.TPDOBPmap.Data=ODPDOmaps(app,'1A00',app.BPnode,4);  %function pdomaps = ODPDOmaps(app,firstindex,n)
%             % 2800.1 sync timing, 23 bytes
            app.BPschedule.Data=double(app.nnp.read(app.BPnode,'2800',1,'uint8'))';
            % 2800.2 sync interval
            app.SyncIntEditField.Value=double(app.nnp.read(app.BPnode,'2800',2,'uint8'));
            
            % 3411.1-7 ch1 config - gain, depth, threshold ZX, threshold SSC, MAVgain, MVgain, WFLgain
            for i=1:7
                temp=double(app.nnp.read(app.BPnode,'3411',i,'uint8'));
                if ~isempty(temp)
                    app.BPchanset(i,1)=temp;
                end
                if ~isempty(temp)
                    temp=double(app.nnp.read(app.BPnode,'3511',i,'uint8'));
                    app.BPchanset(i,2)=temp;
                end
                pause(.1);
            end
            % 3412.6 ch1 features
            app.BPchanset(8,1)=double(app.nnp.read(app.BPnode,'3412',6,'uint8'));
            % 3512.6 ch2 features
            app.BPchanset(8,2)=double(app.nnp.read(app.BPnode,'3512',6,'uint8'));
            app.BPChanCfgTable.Data=app.BPchanset;
            app.ReadingLamp.Enable='off'; %turn lamp off at end
            app.ReadingLamp.Color='k'; %turn back to black/grey when finished 
        end

        % Button pushed function: WriteTestPDOsButton
        function WriteTestPDOsButtonPushed(app, event)
            PDOcfg(app.nnp);
        end

        % Button pushed function: ReadPGTimingButton
        function ReadPGTimingButtonPushed(app, event)
            % When button is pressed I need turn on the network,  to read and distribute the following:
            app.ReadingLamp.Color='g'; %turn green while reading from RM
            app.ReadingLamp.Enable='on'; %turn lamp on at start      
            app.nnp.worOff();
            app.nnp.enterWaiting();
            app.nnp.networkOn();
            pause(1); % wait for network to start
            app.PGnodelist=str2num(cell2mat(app.NodeListStim.Value));
            app.PGtiming2D={[]}; %clear array
            for i=1:size(app.PGnodelist,2)
                n=app.PGnodelist(i);
                app.test1.Value=i;
                app.test2.Value=n;
                %need to read the nodelist data, then include the sitm Vos and min VOS at the end.  Or add that to the getPGtiming function
                app.PGtiming=getPGtiming(app,n);
                if i==1
                    app.PGtiming2D=app.PGtiming;
                else
                    app.PGtiming2D=[app.PGtiming2D app.PGtiming];
                end
                pause(.3);
            end
            app.PGsetupTable.Data=app.PGtiming2D;
            app.ReadingLamp.Enable='off'; %turn lamp off at end
            app.ReadingLamp.Color='k'; %turn back to black/grey when finished 
            app.test1.Value=0; %reset to zero
            app.test2.Value=0;            
            % array=str2num(cell2mat(app.NodeListBP.Value))% this would produce a numerical array
            % 1400-1403 receive PDO configuration, 10 bytes per index
%             app.PGRPDOs_1.Data = ODPDOsettings(app,'1400',n,4);
%             %
%             % 1600-1603 receive PDO mapping, 32 bytes
%             app.PGRPDOm_1.Data=ODPDOmaps(app,'1600',n,4);  %function pdomaps = ODPDOmaps(app,firstindex,n)
%             % pdomaps = getPDOmaps(app,rfileid,firstbyte,n)
%             % 1800-1803 TPDO configuration, 10 bytes per index
%             app.PGTPDOs_1.Data = ODPDOsettings(app,'1800',n,4);
%             % 1A00-1A03 TPDO mapping, 32 bytes \
%             app.PGTPDOm_1.Data=ODPDOmaps(app,'1A00',n,4);  %function pdomaps = ODPDOmaps(app,firstindex,n)
            
%             n=app.PGnodelist(2);
%             app.PGsetupTable_2.Data=getPGtiming(app,n);
%             app.StimVOS_2.Value=double(app.nnp.read(n,'3210',3,'uint16')/60);
%             app.minVOS_2.Value=double(app.nnp.read(n,'3210',4,'uint16')/60);
%             
%             % array=str2num(cell2mat(app.NodeListBP.Value))% this would produce a numerical array
%             % 1400-1403 receive PDO configuration, 10 bytes per index
%             app.PGRPDOs_2.Data = ODPDOsettings(app,'1400',n,4);
%             %
%             % 1600-1603 receive PDO mapping, 32 bytes
%             app.PGRPDOm_2.Data=ODPDOmaps(app,'1600',n,4);  %function pdomaps = ODPDOmaps(app,firstindex,n)
%             % pdomaps = getPDOmaps(app,rfileid,firstbyte,n)
%             % 1800-1803 TPDO configuration, 10 bytes per index
%             app.PGTPDOs_2.Data = ODPDOsettings(app,'1800',n,4);
%             % 1A00-1A03 TPDO mapping, 32 bytes \
%             app.PGTPDOm_2.Data=ODPDOmaps(app,'1A00',n,4);  %function pdomaps = ODPDOmaps(app,firstindex,n)
        end

        % Button pushed function: ReadRemotePDOsButton
        function ReadRemotePDOsButtonPushed(app, event)
            % When the PDOs are read, they will be entered into a 3D cell array, with one dimension being node
            %app.RMnodelength
            %for each node, I need four cell arrays to fill in the four tables
            % I plan to create one multidimensional cell array that will contain all the necessary data.
            % Each node will need 26x4 cells 5cfg 8 map 5 cft 8 map
            % alternatively, I could create a structure array,with each structure containing 4 fields, which would map to the tables.
            %             RMPDOs=struct('RPDOcfg',{[]},'RPDOmap',{[]},'TPDOcfg',{[]},'TPDOmap',{[]});
            % When button is pressed I need turn on the network,  to read and distribute the following:
            app.ReadingLamp.Color='b'; % blue while starting network
            app.ReadingLamp.Enable='on'; %turn lamp on at start
            app.nnp.worOff();
            app.nnp.enterWaiting();
            app.nnp.networkOn();
            pause(1); % wait for network to start
            app.ReadingLamp.Color='g'; %turn green while reading from RM
            pause(.5);
            for i=1:app.RMnodelength
                k=app.RMnumlist(i); % where k=node number
                app.test1.Value=i;
                app.test2.Value=k;
                p=app.RMpdonum(i); % p is number of pdos in that node
                
                % 1400-1403 receive PDO configuration, 10 bytes per index
                 app.RMPDOs(i).RPDOcfg=ODPDOsettings(app,'1400',k,p); %for the first element I want to display result
                % 1600-1603 receive PDO mapping, 32 bytes
                app.RMPDOs(i).RPDOmap=ODPDOmaps(app,'1600',k,p);
                % 1800-1803 TPDO configuration, 10 bytes per index
                app.RMPDOs(i).TPDOcfg = ODPDOsettings(app,'1800',k,p);
                % 1A00-1A03 TPDO mapping, 32 bytes
                app.RMPDOs(i).TPDOmap =ODPDOmaps(app,'1A00',k,p);
                % could add color change for each module
            end
            
            app.ReadingLamp.Color='y'; %turn yellow while updating display
            pause(.5);
            % After reading these display the first one - could make this a function call with i=input         
            i = app.NodeSelect.Value;
            app.RPDO_RMcfg.Data=app.RMPDOs(1).RPDOcfg;
            app.RPDO_RMmap.Data=app.RMPDOs(i).RPDOmap;
            app.TPDO_RMcfg.Data=app.RMPDOs(i).TPDOcfg;
            app.TPDO_RMmap.Data=app.RMPDOs(i).TPDOmap;
            app.ReadingLamp.Color='k'; % back to green then off while starting network
            app.ReadingLamp.Enable='off'; %turn lamp off at end
            app.nnp.worOn(20);
            app.test1.Value=0; %reset to zero
            app.test2.Value=0;

        end

        % Value changed function: NodeListStim
        function NodeListStimValueChanged(app, event)
            UpdateNodeDDlist(app);
        end

        % Value changed function: NodeListBP
        function NodeListBPValueChanged(app, event)
            UpdateNodeDDlist(app);
        end

        % Value changed function: NodeSelect
        function NodeSelectValueChanged(app, event)
            i = app.NodeSelect.Value;
            app.RPDO_RMcfg.Data=app.RMPDOs(i).RPDOcfg; %could make this a function call with i=input
            app.RPDO_RMmap.Data=app.RMPDOs(i).RPDOmap;
            app.TPDO_RMcfg.Data=app.RMPDOs(i).TPDOcfg;
            app.TPDO_RMmap.Data=app.RMPDOs(i).TPDOmap;
        end

        % Button pushed function: DecodeFeaturesButton
        function DecodeFeaturesButtonPushed(app, event)
            DecodeFeatures();
        end

        % Button pushed function: ScriptsDisableButton
        function ScriptsDisableButtonPushed(app, event)
            app.nnp.nmt(7, '8F'); 
        end

        % Button pushed function: ScriptsEnableButton
        function ScriptsEnableButtonPushed(app, event)
            app.nnp.nmt(7, '8E'); 
        end

        % Button pushed function: StartupScriptButton
        function StartupScriptButtonPushed(app, event)
            app.nnp.nmt(7, '8D',7); 
        end

        % Button pushed function: SaveAllButton
        function SaveAllButtonPushed(app, event)
            % This is a quick, brute force way to export all the data to an external file to capture downloaded data
            % It is horribly inefficent, bloated and contains everything in the app - but does at least capture the information as a backup
            % It is needed because AppDesigner currently supports nothing that prints or exports without explicit variable nameing.
            backapp=app; % save doesn't work for all fields, so save and recall from this
            uisave('backapp'); % Save the entire app with its current values for data and strings
        end

        % Button pushed function: GetAllButton
        function GetAllButtonPushed(app, event)
            % Replace entire app structure with what is in file.  This only works correctly if you have not changed the app since
            % creating this structure, so it should be used with caution.
            [fn,pn]=uigetfile('*.mat');
            fullname=fullfile(pn,fn);
            load(fullname,'backapp');    % loads the entire structure into app, replacing current contents
            % below I need a list of fields I want to recall
            app.BPChanCfgTable.Data=backapp.BPChanCfgTable.Data; % only some fields are actually saved
            % This would be just about as tedious as writing each variable one by one to start with.
        end

        % Value changed function: ReadFromSwitch
        function ReadFromSwitchValueChanged(app, event)
            value = app.ReadFromSwitch.Value;
            switch value
                case 'On'
                    app.ReadFromSwitchLabel.Text='From File';
                    app.ReadFromSwitchLabel.BackgroundColor=[1,1,.6];
                case 'Off'
                    app.ReadFromSwitchLabel.Text='From PM';
                    app.ReadFromSwitchLabel.BackgroundColor=[.9,1,1];
            end
        end

        % Button pushed function: SavePGTimingButton
        function SavePGTimingButtonPushed(app, event)
            mydata.data=app.PGsetupTable.Data; % save doesn't work for all fields, so save and recall from this
            mydata.RowName=app.PGsetupTable.RowName;
            uisave('mydata','PGtiming.mat'); % Save the new structure
        end

        % Button pushed function: LoadPGTimingButton
        function LoadPGTimingButtonPushed(app, event)
            [fn,pn]=uigetfile('*.mat');
            fullname=fullfile(pn,fn);
            load(fullname,'mydata');    % loads the entire structure into app, replacing current contents
            % below I need a list of fields I want to recall
            app.PGsetupTable.Data=mydata.data; % replace table with current   
        end

        % Button pushed function: SavePMRestoreButton
        function SavePMRestoreButtonPushed(app, event)
            [fn,pn]=uiputfile('*.bin');
            fullname=fullfile(pn,fn);
            [status,message,messageId]=copyfile(app.RestoreFileName,fullname,'f');
%             if status==0
%                 uialert(figure,[message,' ', messageId],'Error Copying File');
%             end
        end

        % Button pushed function: SaveBPSettingsButton
        function SaveBPSettingsButtonPushed(app, event)
            mydata.bpdata=app.BPschedule.Data; % save doesn't work for all fields, so save and recall from this
            mydata.bpRowName=app.BPschedule.RowName;
            mydata.bpcfgdata=app.BPChanCfgTable.Data;
            mydata.bpcfgRowName=app.BPChanCfgTable.RowName;
            mydata.si=app.SyncIntEditField.Value;
            uisave('mydata','BPcfg.mat'); % Save the new structure     
        end

        % Button pushed function: LoadBPSettingsButton
        function LoadBPSettingsButtonPushed(app, event)
            [fn,pn]=uigetfile('*.mat');
            fullname=fullfile(pn,fn);
            load(fullname,'mydata');    % loads the entire structure into app, replacing current contents
            % below I need a list of fields I want to recall
            app.BPschedule.Data= mydata.bpdata;
            app.BPschedule.RowName=mydata.bpRowName;
            app.BPChanCfgTable.Data=mydata.bpcfgdata;
            app.BPChanCfgTable.RowName=mydata.bpcfgRowName
            app.SyncIntEditField.Value=mydata.si;
        end

        % Button pushed function: SendtoBPButton
        function SendtoBPButtonPushed(app, event)
            %write the data from BPschedule.Data and BPChanCfgTable.Data to BP node
             app.BPnode=str2num(app.NodeListBP.Value{1});
             bpschedule=app.BPschedule.Data';
             app.nnp.write(app.BPnode,'2800',1,bpschedule,'uint8'); % BP schedule
             app.nnp.write(app.BPnode,'2800',2,app.SyncIntEditField.Value,'uint8'); % BP sync
             
             newBP=app.BPChanCfgTable.Data; % 2D array
             for i=1:7
                     app.nnp.write(app.BPnode,'3411',i,newBP(i,1),'uint8'); %channel 1
                     app.nnp.write(app.BPnode,'3511',i,newBP(i,2),'uint8'); %channel 2
                    pause(.1); %time for CAN to catch upend 
             end
              app.nnp.write(app.BPnode,'3412',6,newBP(8,1),'uint8'); %features 1
              app.nnp.write(app.BPnode,'3512',6,newBP(8,2),'uint8'); %features 2
              app.nnp.saveOD(app.BPnode);
             
        end

        % Cell selection callback: ScriptTable
        function ScriptTableCellSelection(app, event)
            %f_clipboard(app.ScriptTable.Data);
            d=app.ScriptTable.Data;
            size_d = size(d);
            str = '';
            for i = 1:size_d(1)
                for j = 1:size_d(2)
                    if j == size_d(2)
                        str = sprintf('%s%f',str,d(i,j));
                    else
                        str = sprintf('%s%f\t',str,d(i,j));
                    end
                end
                str = sprintf('%s\n',str);
            end

            clipboard ('copy',str);
        end

        % Callback function
        function Table1F57CellSelection(app, event)
            
        end

        % Callback function
        function Table1F57bCellSelection(app, event)
            
        end

        % Callback function
        function Table1F57cCellSelection(app, event)
            
        end

        % Button pushed function: ToWorkspaceButton
        function ToWorkspaceButtonPushed(app, event)
            assignin('base','fromApp1F57',app.Table1F57.Data);
%             assignin('base','fromApp1F57a',[app.Table1F57.Data;app.Table1F57b.Data;app.Table1F57c.Data]);
        end

        % Button pushed function: RMInfoButton
        function RMInfoButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.RMTab;
            a=app.NodeListStim.Value;
            b=app.NodeListBP.Value;
            app.RMInfoTextArea.Value=["Please wait, Reading nodes " a b];
            app.ReadingLamp.Color='b'; % blue while starting network
            app.ReadingLamp.Enable='on'; %turn lamp on at start
            app.nnp.worOff();
            app.nnp.enterWaiting();
            app.nnp.networkOn();
            pause(1); % wait for network to start
            app.ReadingLamp.Color='g'; %turn green while reading from RM
            pause(.5);
            % call the funvtion to read the RM data
            
            StrA=rmSettingsReadFcn(app.nnp,app.RMnumlist);
            app.RMInfoTextArea.Value=StrA;
            
            app.ReadingLamp.Color='y'; %turn yellow while updating display
            pause(.5);
            % After reading these display the first one - could make this a function call with i=input         


            app.ReadingLamp.Color='k'; % back to green then off while starting network
            app.ReadingLamp.Enable='off'; %turn lamp off at end
            app.nnp.worOn(20);
            app.test1.Value=0; %reset to zero
            app.test2.Value=0;

        end

        % Button pushed function: ToPMButton
        function ToPMButtonPushed(app, event)
            %now that the data is a normal table, not a cell array, need to
            %just convert table to array
            T57=app.Table1F57.Data; %T57 is now a normal table
            Data57=uint8(T57{:,2}); % convert to uint8 array

%           % for uint8, need something like A=uint8(B{1,2});

          app.nnp.write(7,'1F57',1,50,Data57(1:50),'uint8'); % 1F57 index data first 50
          app.nnp.write(7,'1F57',51,50,Data57(51:100),'uint8'); % 1F57 index data second 50
          if app.PMR405
              app.nnp.write(7,'1F57',101,50,Data57(101:150),'uint8'); % 1F57 index data second 50
          end
        end

        % Close request function: ReadRestoreUI
        function ReadRestoreUICloseRequest(app, event)
            if ishandle(app.callingFigure) % check that is is valid
                figure(app.callingFigure); % bring to front
            end
            delete(app)

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ReadRestoreUI = uifigure('Visible', 'off');
            app.ReadRestoreUI.Name = 'ReadUserData';
            app.ReadRestoreUI.CloseRequestFcn = createCallbackFcn(app, @ReadRestoreUICloseRequest, true);
            app.ReadandRestoreUserDataLabel = uilabel(app.ReadRestoreUI);
            app.ReadandRestoreUserDataLabel.Text = 'Read and Restore User Data';
            app.ReadPMRestoreButton = uibutton(app.ReadRestoreUI, 'push');
            app.ReadPMRestoreButton.ButtonPushedFcn = createCallbackFcn(app, @ReadPMRestoreButtonPushed, true);
            app.ReadPMRestoreButton.Text = {'Read PM'; 'Restore'};
            app.ReadPGTimingButton = uibutton(app.ReadRestoreUI, 'push');
            app.ReadPGTimingButton.ButtonPushedFcn = createCallbackFcn(app, @ReadPGTimingButtonPushed, true);
            app.ReadPGTimingButton.Text = {'Read PG'; 'Timing'};
            app.ReadBPSettingsButton = uibutton(app.ReadRestoreUI, 'push');
            app.ReadBPSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ReadBPSettingsButtonPushed, true);
            app.ReadBPSettingsButton.Text = {'Read BP'; 'Settings'};
            app.ConfigAPButton = uibutton(app.ReadRestoreUI, 'push');
            app.ConfigAPButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigAPButtonPushed, true);
            app.ConfigAPButton.Text = 'Radio Configuration';
            app.TabGroup = uitabgroup(app.ReadRestoreUI);
            app.ConfigTab = uitab(app.TabGroup);
            app.ConfigTab.Title = 'Config';
            app.NodeListStim = uitextarea(app.ConfigTab);
            app.NodeListStim.ValueChangedFcn = createCallbackFcn(app, @NodeListStimValueChanged, true);
            app.NodeListStim.Value = {'1 2 3 4 5 6'};
            app.NodeListBP = uitextarea(app.ConfigTab);
            app.NodeListBP.ValueChangedFcn = createCallbackFcn(app, @NodeListBPValueChanged, true);
            app.NodeListBP.Value = {'9'};
            app.StimNodesLabel = uilabel(app.ConfigTab);
            app.StimNodesLabel.Text = {'Stim'; 'Nodes'};
            app.MESNodesLabel = uilabel(app.ConfigTab);
            app.MESNodesLabel.Text = {'MES'; 'Nodes'};
            app.PMVersionEditFieldLabel = uilabel(app.ConfigTab);
            app.PMVersionEditFieldLabel.Text = 'PMVersion';
            app.PMVersionEditField = uieditfield(app.ConfigTab, 'numeric');
            app.PMVersionEditField.Value = 414;
            app.BasicTab = uitab(app.TabGroup);
            app.BasicTab.Title = 'Basic';
            app.RbytesEditFieldLabel = uilabel(app.BasicTab);
            app.RbytesEditFieldLabel.Text = 'Rbytes';
            app.RbytesEditField = uieditfield(app.BasicTab, 'numeric');
            app.RindicesEditFieldLabel = uilabel(app.BasicTab);
            app.RindicesEditFieldLabel.Text = 'Rindices';
            app.RindicesEditField = uieditfield(app.BasicTab, 'numeric');
            app.RsyncEditFieldLabel = uilabel(app.BasicTab);
            app.RsyncEditFieldLabel.Text = 'Rsync';
            app.RsyncEditField = uieditfield(app.BasicTab, 'numeric');
            app.DateBatteryNetworkgohereLabel = uilabel(app.BasicTab);
            app.DateBatteryNetworkgohereLabel.Text = 'Date, Battery, Network go here';
            app.TableDateTime = uitable(app.BasicTab);
            app.TableDateTime.ColumnName = {'data'};
            app.TableDateTime.RowName = {};
            app.Thisisjustaplaceholderfordate2004untilIsortoutthenumbersLabel = uilabel(app.BasicTab);
            app.Thisisjustaplaceholderfordate2004untilIsortoutthenumbersLabel.Text = 'This is just a placeholder for date 2004 until I sort out the numbers';
            app.MultiplierEditFieldLabel = uilabel(app.BasicTab);
            app.MultiplierEditFieldLabel.Text = 'Multiplier';
            app.MultiplierEditField = uieditfield(app.BasicTab, 'numeric');
            app.RPDOPMTab = uitab(app.TabGroup);
            app.RPDOPMTab.Title = 'RPDO-PM';
            app.x1400Label = uilabel(app.RPDOPMTab);
            app.x1400Label.Text = '0x1400';
            app.UITableRS = uitable(app.RPDOPMTab);
            app.UITableRS.ColumnName = {'PDO1'; 'PDO2'; 'PDO3'; 'PDO4'; 'PDO5'; 'PDO6'; 'PDO7'; 'PDO8'};
            app.UITableRS.RowName = {};
            app.UITable = uitable(app.RPDOPMTab);
            app.UITable.ColumnName = {'PDO1'; 'PDO2'; 'PDO3'; 'PDO4'; 'PDO5'; 'PDO6'; 'PDO7'; 'PDO8'};
            app.UITable.RowName = {};
            app.x1600Label = uilabel(app.RPDOPMTab);
            app.x1600Label.Text = '0x1600';
            app.Label_3 = uilabel(app.RPDOPMTab);
            app.Label_3.Text = 'Need to switch where channel 1 and 2 of MES are routed such that the logical and proportional channels end up in the right location.';
            app.TPDOPMTab = uitab(app.TabGroup);
            app.TPDOPMTab.Title = 'TPDO-PM';
            app.x1800Label = uilabel(app.TPDOPMTab);
            app.x1800Label.Text = '0x1800';
            app.UITableTS = uitable(app.TPDOPMTab);
            app.UITableTS.ColumnName = {'PDO1'; 'PDO2'; 'PDO3'; 'PDO4'; 'PDO5'; 'PDO6'; 'PDO7'; 'PDO8'};
            app.UITableTS.RowName = {};
            app.UITableTM = uitable(app.TPDOPMTab);
            app.UITableTM.ColumnName = {'PDO1'; 'PDO2'; 'PDO3'; 'PDO4'; 'PDO5'; 'PDO6'; 'PDO7'; 'PDO8'};
            app.UITableTM.RowName = {};
            app.x1A00Label = uilabel(app.TPDOPMTab);
            app.x1A00Label.Text = '0x1A00';
            app.ScriptsTab = uitab(app.TabGroup);
            app.ScriptsTab.Title = 'Scripts';
            app.F51scriptpointers1F56scriptorderLabel = uilabel(app.ScriptsTab);
            app.F51scriptpointers1F56scriptorderLabel.Text = '1F51 script pointers,  1F56 script order';
            app.ScriptTable = uitable(app.ScriptsTab);
            app.ScriptTable.ColumnName = {'Pointer'; 'ID'; 'Status'; 'Error'; 'Order'};
            app.ScriptTable.RowName = {};
            app.ScriptTable.ColumnEditable = [true false false false false];
            app.ScriptTable.CellSelectionCallback = createCallbackFcn(app, @ScriptTableCellSelection, true);
            app.AddsomethinglatertochangesortorderLabel = uilabel(app.ScriptsTab);
            app.AddsomethinglatertochangesortorderLabel.Text = 'Add something later to change sort order';
            app.ScriptSortOrderDropDownLabel = uilabel(app.ScriptsTab);
            app.ScriptSortOrderDropDownLabel.Text = 'ScriptSortOrder';
            app.ScriptSortOrderDropDown = uidropdown(app.ScriptsTab);
            app.ScriptSortOrderDropDown.Items = {'Pointer', 'ID', 'Order'};
            app.ScriptSortOrderDropDown.ItemsData = {'1', '2', '5'};
            app.ScriptSortOrderDropDown.ValueChangedFcn = createCallbackFcn(app, @ScriptSortOrderDropDownValueChanged, true);
            app.ScriptSortOrderDropDown.Value = '1';
            app.F57Tab = uitab(app.TabGroup);
            app.F57Tab.Title = '1F57';
            app.UserparametersmaydistributeoverseveraltabsLabel = uilabel(app.F57Tab);
            app.UserparametersmaydistributeoverseveraltabsLabel.Text = 'User parameters - may distribute over several tabs';
            app.Table1F57 = uitable(app.F57Tab);
            app.Table1F57.ColumnName = {'Label'; 'Data'};
            app.Table1F57.RowName = {};
            app.Table1F57.ColumnEditable = [false true];
            app.ToWorkspaceButton = uibutton(app.F57Tab, 'push');
            app.ToWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ToWorkspaceButtonPushed, true);
            app.ToWorkspaceButton.Text = 'To Workspace';
            app.Label_6 = uilabel(app.F57Tab);
            app.Label_6.Text = 'Cannot copy string and numbers at same time in Matlab, so used assignin function.  ';
            app.ToPMButton = uibutton(app.F57Tab, 'push');
            app.ToPMButton.ButtonPushedFcn = createCallbackFcn(app, @ToPMButtonPushed, true);
            app.ToPMButton.Text = 'To PM';
            app.Label_7 = uilabel(app.F57Tab);
            app.Label_7.Text = {'Need to read in PM version and change how I interpret the restore data.'; 'Starting with firmware 405'; ' the OD index 1F57 has 150 elements rather than only 100.  '; 'That will affect all the starting pointers after it.'};
            app.SysControlTab = uitab(app.TabGroup);
            app.SysControlTab.Title = 'Sys Control';
            app.SystemcontrollnoteLabel = uilabel(app.SysControlTab);
            app.SystemcontrollnoteLabel.Text = 'System control - might go on the basic tab';
            app.SystemControlEditFieldLabel = uilabel(app.SysControlTab);
            app.SystemControlEditFieldLabel.Text = 'SystemControl';
            app.SystemControlEditField = uieditfield(app.SysControlTab, 'text');
            app.CurrentGroupEditFieldLabel = uilabel(app.SysControlTab);
            app.CurrentGroupEditFieldLabel.Text = 'Current Group';
            app.CurrentGroupEditField = uieditfield(app.SysControlTab, 'numeric');
            app.StartupScriptPointerEditFieldLabel = uilabel(app.SysControlTab);
            app.StartupScriptPointerEditFieldLabel.Text = 'StartupScriptPointer';
            app.StartupScriptPointerEditField = uieditfield(app.SysControlTab, 'numeric');
            app.ShutdownScriptPointerEditFieldLabel = uilabel(app.SysControlTab);
            app.ShutdownScriptPointerEditFieldLabel.Text = 'ShutdownScriptPointer';
            app.ShutdownScriptPointerEditField = uieditfield(app.SysControlTab, 'numeric');
            app.ScriptDelayTimeEditFieldLabel = uilabel(app.SysControlTab);
            app.ScriptDelayTimeEditFieldLabel.Text = 'ScriptDelayTime';
            app.ScriptDelayTimeEditField = uieditfield(app.SysControlTab, 'numeric');
            app.BatteryControlLabel = uilabel(app.SysControlTab);
            app.BatteryControlLabel.Text = 'Battery Control';
            app.PowerControlEditFieldLabel = uilabel(app.SysControlTab);
            app.PowerControlEditFieldLabel.Text = 'PowerControl';
            app.PowerControlEditField = uieditfield(app.SysControlTab, 'numeric');
            app.StepIncrementEditFieldLabel = uilabel(app.SysControlTab);
            app.StepIncrementEditFieldLabel.Text = 'StepIncrement';
            app.StepIncrementEditField = uieditfield(app.SysControlTab, 'numeric');
            app.ChargingIntervalEditFieldLabel = uilabel(app.SysControlTab);
            app.ChargingIntervalEditFieldLabel.Text = 'Charging Interval';
            app.ChargingIntervalEditField = uieditfield(app.SysControlTab, 'numeric');
            app.NoChargeLimitEditFieldLabel = uilabel(app.SysControlTab);
            app.NoChargeLimitEditFieldLabel.Text = 'No Charge Limit';
            app.NoChargeLimitEditField = uieditfield(app.SysControlTab, 'numeric');
            app.StepLimitEditFieldLabel = uilabel(app.SysControlTab);
            app.StepLimitEditFieldLabel.Text = 'StepLimit';
            app.StepLimitEditField = uieditfield(app.SysControlTab, 'numeric');
            app.ChargeRunEditFieldLabel = uilabel(app.SysControlTab);
            app.ChargeRunEditFieldLabel.Text = 'ChargeRun';
            app.ChargeRunEditField = uieditfield(app.SysControlTab, 'numeric');
            app.VrecEditFieldLabel = uilabel(app.SysControlTab);
            app.VrecEditFieldLabel.Text = 'Vrec';
            app.VrecEditField = uieditfield(app.SysControlTab, 'numeric');
            app.VsysEditFieldLabel = uilabel(app.SysControlTab);
            app.VsysEditFieldLabel.Text = 'Vsys';
            app.VsysEditField = uieditfield(app.SysControlTab, 'numeric');
            app.VnetEditFieldLabel = uilabel(app.SysControlTab);
            app.VnetEditFieldLabel.Text = 'Vnet';
            app.VnetEditField = uieditfield(app.SysControlTab, 'numeric');
            app.LoadEditFieldLabel = uilabel(app.SysControlTab);
            app.LoadEditFieldLabel.Text = 'Load';
            app.LoadEditField = uieditfield(app.SysControlTab, 'numeric');
            app.RequestCurrentEditFieldLabel = uilabel(app.SysControlTab);
            app.RequestCurrentEditFieldLabel.Text = 'Request Current';
            app.RequestCurrentEditField = uieditfield(app.SysControlTab, 'numeric');
            app.CommandCurrentEditFieldLabel = uilabel(app.SysControlTab);
            app.CommandCurrentEditFieldLabel.Text = 'Command Current';
            app.CommandCurrentEditField = uieditfield(app.SysControlTab, 'numeric');
            app.MinVsysEditFieldLabel = uilabel(app.SysControlTab);
            app.MinVsysEditFieldLabel.Text = 'MinVsys';
            app.MinVsysEditField = uieditfield(app.SysControlTab, 'numeric');
            app.LowPowerStatusEditFieldLabel = uilabel(app.SysControlTab);
            app.LowPowerStatusEditFieldLabel.Text = 'LowPowerStatus';
            app.LowPowerStatusEditField = uieditfield(app.SysControlTab, 'numeric');
            app.UITable2 = uitable(app.SysControlTab);
            app.UITable2.ColumnName = {'ChargingData'};
            app.UITable2.RowName = {};
            app.NetworkVoltageEditFieldLabel = uilabel(app.SysControlTab);
            app.NetworkVoltageEditFieldLabel.Text = 'Network Voltage';
            app.NetworkVoltageEditField = uieditfield(app.SysControlTab, 'numeric');
            app.couldaddbuttonstoupdateanyoftheitemsIwouldwanttochangeLabel = uilabel(app.SysControlTab);
            app.couldaddbuttonstoupdateanyoftheitemsIwouldwanttochangeLabel.Text = {'could add buttons to update any of the items I would want to change'; 'then add button for ''save'''};
            app.RM_PDOsTab = uitab(app.TabGroup);
            app.RM_PDOsTab.Title = 'RM_PDOs';
            app.x1400Label_5 = uilabel(app.RM_PDOsTab);
            app.x1400Label_5.Text = '0x1400';
            app.RPDO_RMcfg = uitable(app.RM_PDOsTab);
            app.RPDO_RMcfg.ColumnName = {'PDO1'; 'PDO2'; 'PDO3'; 'PDO4'};
            app.RPDO_RMcfg.RowName = {};
            app.RPDO_RMmap = uitable(app.RM_PDOsTab);
            app.RPDO_RMmap.ColumnName = {'PDO1'; 'PDO2'; 'PDO3'; 'PDO4'};
            app.RPDO_RMmap.RowName = {};
            app.x1600Label_5 = uilabel(app.RM_PDOsTab);
            app.x1600Label_5.Text = '0x1600';
            app.Label_5 = uilabel(app.RM_PDOsTab);
            app.Label_5.Text = 'Need to distinguish between pg and bp because bp has 4 and pg as one PDO.  So need another array to record whether 1 or 4 pdo';
            app.x1800Label_5 = uilabel(app.RM_PDOsTab);
            app.x1800Label_5.Text = '0x1800';
            app.TPDO_RMcfg = uitable(app.RM_PDOsTab);
            app.TPDO_RMcfg.ColumnName = {'PDO1'; 'PDO2'; 'PDO3'; 'PDO4'};
            app.TPDO_RMcfg.RowName = {};
            app.TPDO_RMmap = uitable(app.RM_PDOsTab);
            app.TPDO_RMmap.ColumnName = {'PDO1'; 'PDO2'; 'PDO3'; 'PDO4'};
            app.TPDO_RMmap.RowName = {};
            app.x1A00Label_5 = uilabel(app.RM_PDOsTab);
            app.x1A00Label_5.Text = '0x1A00';
            app.NodeLabel = uilabel(app.RM_PDOsTab);
            app.NodeLabel.Text = 'Node';
            app.NodeSelect = uidropdown(app.RM_PDOsTab);
            app.NodeSelect.Items = {'1', '2', '3', '4', '5', '6', '9'};
            app.NodeSelect.ValueChangedFcn = createCallbackFcn(app, @NodeSelectValueChanged, true);
            app.NodeSelect.Value = '1';
            app.ReceiveLabel = uilabel(app.RM_PDOsTab);
            app.ReceiveLabel.Text = 'Receive';
            app.TransmitLabel = uilabel(app.RM_PDOsTab);
            app.TransmitLabel.Text = 'Transmit';
            app.SettingsLabel = uilabel(app.RM_PDOsTab);
            app.SettingsLabel.Text = 'Settings';
            app.MappingsLabel = uilabel(app.RM_PDOsTab);
            app.MappingsLabel.Text = 'Mappings';
            app.PGTab = uitab(app.TabGroup);
            app.PGTab.Title = 'PG';
            app.Label = uilabel(app.PGTab);
            app.Label.Text = 'Display the PG settings for each module in columns.  This will be used in the next tab for plotting the PG vs BP timing';
            app.PGsetupTable = uitable(app.PGTab);
            app.PGsetupTable.ColumnName = {'1'; '2'; '3'; '4'; '5'; '6'};
            app.PGsetupTable.RowName = {'{''Node'',''Stim Timing'',''Sync Interval'',''SyncPush'',''AutoSyncTime'',''MaxAutoSyncCount'',''MinDischargeTime'',''StimVOS'',''minVOS''}'; ''};
            app.PGsetupTable.ColumnEditable = true;
            app.BPTab = uitab(app.TabGroup);
            app.BPTab.Title = 'BP';
            app.UIAxes = uiaxes(app.BPTab);
            title(app.UIAxes, 'Sync Timing')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [0 100];
            app.UIAxes.YLim = [0 3];
            app.BPschedule = uitable(app.BPTab);
            app.BPschedule.ColumnName = {'BPschedule'};
            app.BPschedule.RowName = {};
            app.BPschedule.ColumnEditable = true;
            app.SyncIntEditFieldLabel = uilabel(app.BPTab);
            app.SyncIntEditFieldLabel.Text = 'Sync Int';
            app.SyncIntEditField = uieditfield(app.BPTab, 'numeric');
            app.GainEditFieldLabel = uilabel(app.BPTab);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.BPTab, 'numeric');
            app.testEditFieldLabel = uilabel(app.BPTab);
            app.testEditFieldLabel.Text = 'test';
            app.testEditField = uieditfield(app.BPTab, 'numeric');
            app.synctiming3411ch1config3511ch2config3412ch1featuresLabel = uilabel(app.BPTab);
            app.synctiming3411ch1config3511ch2config3412ch1featuresLabel.Text = {'2800 sync timing'; '3411 ch1 config'; '3511 ch2 config'; '3412 ch1 features'; '3512 ch2 features'};
            app.CurrentGroupEditField_2Label = uilabel(app.BPTab);
            app.CurrentGroupEditField_2Label.Text = 'CurrentGroup';
            app.CurrentGroupEditField_2 = uieditfield(app.BPTab, 'text');
            app.BPChanCfgTable = uitable(app.BPTab);
            app.BPChanCfgTable.ColumnName = {'Chan 1'; 'Chan 2'};
            app.BPChanCfgTable.RowName = {};
            app.BPChanCfgTable.ColumnEditable = true;
            app.NeedtoupdatethissynctimingLabel = uilabel(app.BPTab);
            app.NeedtoupdatethissynctimingLabel.Text = {'Need to update this sync timing'; 'plot to show both PG and BP timing'; 'I have a script to do this, called'; 'MyStemPlot.m, but have'; 'not yet added it here'};
            app.DecodeFeaturesButton = uibutton(app.BPTab, 'push');
            app.DecodeFeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @DecodeFeaturesButtonPushed, true);
            app.DecodeFeaturesButton.Text = {'Decode'; 'Features'};
            app.DecodeorsetfeaturesinseparatewindowLabel = uilabel(app.BPTab);
            app.DecodeorsetfeaturesinseparatewindowLabel.Text = {'Decode or set features'; 'in separate window.'; 'First pass is just for reference'; 'So data is not passed'};
            app.RMTab = uitab(app.TabGroup);
            app.RMTab.Title = 'RM';
            app.RMInfoTextAreaLabel = uilabel(app.RMTab);
            app.RMInfoTextAreaLabel.Text = 'RM Info';
            app.RMInfoTextArea = uitextarea(app.RMTab);
            app.needsaveasbuttontosaveconfigurationLabel = uilabel(app.ReadRestoreUI);
            app.needsaveasbuttontosaveconfigurationLabel.Text = {'need save as '; 'button to save'; 'configuration'};
            app.Label_2 = uilabel(app.ReadRestoreUI);
            app.Label_2.Text = {'For now the restore data is store in temp '; 'file called TempRestoreFileName.bin'; 'could make this an input,then I would have '; 'a saved copy'};
            app.PlotBinaryFileButton = uibutton(app.ReadRestoreUI, 'push');
            app.PlotBinaryFileButton.ButtonPushedFcn = createCallbackFcn(app, @PlotBinaryFileButtonPushed, true);
            app.PlotBinaryFileButton.Text = 'Plot Binary File';
            app.AddfeatureselecttobottomrowLabel = uilabel(app.ReadRestoreUI);
            app.AddfeatureselecttobottomrowLabel.Text = {'Add feature select to bottom row'; 'of channel config ore create separate'; 'table with checkmarks?  Add button '; 'to copy 1F57 back to PM after editing'};
            app.WriteTestPDOsButton = uibutton(app.ReadRestoreUI, 'push');
            app.WriteTestPDOsButton.ButtonPushedFcn = createCallbackFcn(app, @WriteTestPDOsButtonPushed, true);
            app.WriteTestPDOsButton.Text = 'WriteTest PDOs';
            app.ReadRemotePDOsButton = uibutton(app.ReadRestoreUI, 'push');
            app.ReadRemotePDOsButton.ButtonPushedFcn = createCallbackFcn(app, @ReadRemotePDOsButtonPushed, true);
            app.ReadRemotePDOsButton.Text = {'Read Remote'; 'PDOs'};
            app.ScriptsDisableButton = uibutton(app.ReadRestoreUI, 'push');
            app.ScriptsDisableButton.ButtonPushedFcn = createCallbackFcn(app, @ScriptsDisableButtonPushed, true);
            app.ScriptsDisableButton.Text = 'Scripts Disable';
            app.ScriptsEnableButton = uibutton(app.ReadRestoreUI, 'push');
            app.ScriptsEnableButton.ButtonPushedFcn = createCallbackFcn(app, @ScriptsEnableButtonPushed, true);
            app.ScriptsEnableButton.Text = 'Scripts Enable';
            app.StartupScriptButton = uibutton(app.ReadRestoreUI, 'push');
            app.StartupScriptButton.ButtonPushedFcn = createCallbackFcn(app, @StartupScriptButtonPushed, true);
            app.StartupScriptButton.Text = 'Startup Script';
            app.SaveAllButton = uibutton(app.ReadRestoreUI, 'push');
            app.SaveAllButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAllButtonPushed, true);
            app.SaveAllButton.Text = 'SaveAll';
            app.GetAllButton = uibutton(app.ReadRestoreUI, 'push');
            app.GetAllButton.ButtonPushedFcn = createCallbackFcn(app, @GetAllButtonPushed, true);
            app.GetAllButton.Text = 'GetAll';
            app.ReadFromSwitchLabel = uilabel(app.ReadRestoreUI);
            app.ReadFromSwitchLabel.Text = 'From PM';
            app.ReadFromSwitch = uiswitch(app.ReadRestoreUI, 'rocker');
            app.ReadFromSwitch.ValueChangedFcn = createCallbackFcn(app, @ReadFromSwitchValueChanged, true);
            app.SavePGTimingButton = uibutton(app.ReadRestoreUI, 'push');
            app.SavePGTimingButton.ButtonPushedFcn = createCallbackFcn(app, @SavePGTimingButtonPushed, true);
            app.SavePGTimingButton.Text = {'Save '; 'PG Timing'};
            app.LoadPGTimingButton = uibutton(app.ReadRestoreUI, 'push');
            app.LoadPGTimingButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPGTimingButtonPushed, true);
            app.LoadPGTimingButton.Text = {'Load'; 'PG Timing'};
            app.SavePMRestoreButton = uibutton(app.ReadRestoreUI, 'push');
            app.SavePMRestoreButton.ButtonPushedFcn = createCallbackFcn(app, @SavePMRestoreButtonPushed, true);
            app.SavePMRestoreButton.Text = 'Save';
            app.SaveBPSettingsButton = uibutton(app.ReadRestoreUI, 'push');
            app.SaveBPSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveBPSettingsButtonPushed, true);
            app.SaveBPSettingsButton.Text = {'Save '; 'BP Settings'};
            app.LoadBPSettingsButton = uibutton(app.ReadRestoreUI, 'push');
            app.LoadBPSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadBPSettingsButtonPushed, true);
            app.LoadBPSettingsButton.Text = {'Load'; 'BP Settings'};
            app.SendtoBPButton = uibutton(app.ReadRestoreUI, 'push');
            app.SendtoBPButton.ButtonPushedFcn = createCallbackFcn(app, @SendtoBPButtonPushed, true);
            app.SendtoBPButton.Text = {'Send to '; 'BP'};
            app.ReadingPanel = uipanel(app.ReadRestoreUI);
            app.ReadingPanel.Title = 'Reading';
            app.ReadingLampLabel = uilabel(app.ReadingPanel);
            app.ReadingLampLabel.Text = 'Reading';
            app.ReadingLamp = uilamp(app.ReadingPanel);
            app.ReadingLamp.Enable = 'off';
            app.indLabel = uilabel(app.ReadingPanel);
            app.indLabel.Text = 'ind';
            app.test1 = uieditfield(app.ReadingPanel, 'numeric');
            app.test1.Editable = 'off';
            app.nodeLabel = uilabel(app.ReadingPanel);
            app.nodeLabel.Text = 'node';
            app.test2 = uieditfield(app.ReadingPanel, 'numeric');
            app.test2.Editable = 'off';
            app.RMInfoButton = uibutton(app.ReadRestoreUI, 'push');
            app.RMInfoButton.ButtonPushedFcn = createCallbackFcn(app, @RMInfoButtonPushed, true);
            app.RMInfoButton.Text = 'RM Info';
            % Show the figure after all components are created
            app.ReadRestoreUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pmRestoreFileDisplay(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ReadRestoreUI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ReadRestoreUI)
        end
    end
end