classdef pmbootloader < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PowerModuleBootloaderUIFigure  matlab.ui.Figure
        HelpMenu                       matlab.ui.container.Menu
        ReadAppStatus                  matlab.ui.control.Button
        PowerModuleFlashPanel          matlab.ui.container.Panel
        StartButton                    matlab.ui.control.Button
        EraseCheckBox                  matlab.ui.control.CheckBox
        BlankCheckCheckBox             matlab.ui.control.CheckBox
        VerifyCheckBox                 matlab.ui.control.CheckBox
        WriteCheckBox                  matlab.ui.control.CheckBox
        EntireFlashCheckBox            matlab.ui.control.CheckBox
        IncludingscriptsandappdataLabel  matlab.ui.control.Label
        PMApplicationRadioSettingsPanel  matlab.ui.container.Panel
        APAddressEditFieldLabel        matlab.ui.control.Label
        APAddressEditField             matlab.ui.control.NumericEditField
        PMAddressEditFieldLabel        matlab.ui.control.Label
        PMAddressEditField             matlab.ui.control.NumericEditField
        ChannelEditFieldLabel          matlab.ui.control.Label
        ChannelEditField               matlab.ui.control.NumericEditField
        TXPowerEditFieldLabel          matlab.ui.control.Label
        TXPowerEditField               matlab.ui.control.NumericEditField
        WriteButton                    matlab.ui.control.Button
        ReadButton                     matlab.ui.control.Button
        SetAPButton                    matlab.ui.control.Button
        RadioWarningLabel              matlab.ui.control.Label
        PMSerialEditFieldLabel         matlab.ui.control.Label
        PMSerialEditField              matlab.ui.control.NumericEditField
        RestoreODDefaultsButton        matlab.ui.control.Button
        RSSIEditFieldLabel             matlab.ui.control.Label
        APRSSIEditField                matlab.ui.control.NumericEditField
        LQIEditFieldLabel              matlab.ui.control.Label
        APLQIEditField                 matlab.ui.control.NumericEditField
        EnterBootloaderButton          matlab.ui.control.Button
        ExitBootloaderButton           matlab.ui.control.Button
        AttemptPMResetButton           matlab.ui.control.Button
        RestoreEditFieldLabel          matlab.ui.control.Label
        RestoreEditField               matlab.ui.control.NumericEditField
        TempCLabel                     matlab.ui.control.Label
        TempEditField                  matlab.ui.control.NumericEditField
        PMLQIEditField                 matlab.ui.control.NumericEditField
        PMRSSIEditField                matlab.ui.control.NumericEditField
        APLabel                        matlab.ui.control.Label
        PMLabel                        matlab.ui.control.Label
        BootRevEditField_2Label        matlab.ui.control.Label
        BootRevEditField               matlab.ui.control.EditField
        AppRevEditFieldLabel           matlab.ui.control.Label
        AppRevEditField                matlab.ui.control.EditField
    end

    
    properties (Access = public)
        nnp = []; % Description
        savedSettings = [];
    end
    
    methods (Access = private)
        
        function enableAll(app, status)
            if status == 1 %bootMode
                enable = 'on';
                disable = 'off';
            else
                enable = 'off';
                disable = 'on';
            end
            app.ExitBootloaderButton.Enable = enable;
            app.StartButton.Enable = enable;
            app.ReadButton.Enable = enable;
            app.WriteButton.Enable = enable;
            app.RestoreODDefaultsButton.Enable = enable;
            app.EraseCheckBox.Enable = enable;
            app.BlankCheckCheckBox.Enable = enable;
            app.WriteCheckBox.Enable = enable;
            app.VerifyCheckBox.Enable = enable;
            
            
            app.BootRevEditField.Enable = enable;
            app.RestoreEditField.Enable = enable;
            
            app.AttemptPMResetButton.Enable = disable;
            app.ReadAppStatus.Enable = disable;
            app.AppRevEditField.Enable = disable;
            app.TempEditField.Enable = disable;
            
            %PMSerial, LQI, RSSI are always enabled
        end
        
        function [rssi, lqi, crc] = calcRadio(app, rssiraw, lqiraw)
            rssioffset = 74;
            
            if rssiraw < 128
                rssi = rssiraw/2 - rssioffset;
            else
                rssi = (rssiraw-256)/2 - rssioffset;
            end
            if lqiraw >= 128
                lqi = lqiraw - 128;
                crc = true;
            else
                lqi = lqiraw; %bad CRC
                crc = false;
            end
        end
        
        function restoreRadio(app)
            if app.savedSettings.worInt < 14
                resp = questdlg('AP Wake Interval is currently set below 14ms, which may limit ability to communicate with PM while running application.  Would you like to set it to the default (20ms)?');
                if isequal(resp, 'Yes')
                    app.savedSettings.worInt = 20;
                end
            end
            settingsOut = app.nnp.setRadioSettings(app.savedSettings);
            if ~isequal(app.savedSettings, settingsOut)
                msgbox('could not configure AP to specified settings', 'AP Configuration', 'error')
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, nnp)
            if isempty(nnp)
                app.nnp = NNPAPI();
                if isempty(app.nnp.port)
                    msgbox('No port chosen for NNP Access Point')
                    delete(app.nnp)
                    return
                end
            else
                app.nnp = nnp;
            end
            
            app.enableAll(0);
            app.savedSettings = app.nnp.getRadioSettings();
            
            app.APAddressEditField.Value = app.savedSettings.addrAP;
            app.PMAddressEditField.Value = app.savedSettings.addrPM;
            app.ChannelEditField.Value = app.savedSettings.chan;
            app.TXPowerEditField.Value = app.savedSettings.txPower;
            
            app.RadioWarningLabel.Visible = 'on';
            
            %read temp, serial number, and App rev if possible
            app.ReadAppStatusPushed();
        end

        % Button pushed function: ReadAppStatus
        function ReadAppStatusPushed(app, event)
            resp = app.nnp.getSerial(7);
            if length(resp) == 1
                app.PMSerialEditField.Value = resp;
            end
            
            resp = app.nnp.getSwRev(7);
            if length(resp) == 1
                app.AppRevEditField.Value = sprintf('%u', resp);
            end
            
            resp = app.nnp.getTemp(7);
            if length(resp) == 1
                app.TempEditField.Value = resp;
            end
            
            resp = double(app.nnp.read(7, '2600',1, 'uint8', 6));
            if length(resp) ==6
                [app.PMRSSIEditField.Value, app.PMLQIEditField.Value]=app.calcRadio(resp(5), resp(6));
                
                app.APAddressEditField.Value = resp(2);
                app.PMAddressEditField.Value = resp(1);
                app.ChannelEditField.Value = resp(3);
                app.TXPowerEditField.Value = resp(4);
                
                app.RadioWarningLabel.Visible = 'off';
                
                app.APRSSIEditField.Value = app.nnp.RSSI;
                app.APLQIEditField.Value = app.nnp.LQI;
            end
        end

        % Button pushed function: RestoreODDefaultsButton
        function RestoreODDefaultsButtonPushed(app, event)
            
        end

        % Button pushed function: ReadButton
        function ReadButtonPushed(app, event)
            
            resp = double(app.nnp.pmboot('17'));
            if length(resp) == 5 && resp(1) == hex2dec('27')
                if resp(3) > app.APAddressEditField.Limits(2) || ...
                        resp(3) < app.APAddressEditField.Limits(1) ||...
                        resp(2) > app.PMAddressEditField.Limits(2) || ...
                        resp(2) < app.PMAddressEditField.Limits(1) ||...
                        resp(4) > app.ChannelEditField.Limits(2) || ...
                        resp(4) < app.ChannelEditField.Limits(1) ||...
                        resp(5) > app.TXPowerEditField.Limits(2) || ...
                        resp(5) < app.TXPowerEditField.Limits(1)
                    
                    if all(resp(2:5) == 255)
                        app.RadioWarningLabel.Text = {'PM app radio settings were deleted';'or never set. Setting defaults.' };
                        app.APAddressEditField.Value = 3;
                        app.PMAddressEditField.Value = 4;
                        app.ChannelEditField.Value = 5;
                        app.TXPowerEditField.Value = 20;
                        
                        app.RadioWarningLabel.Visible = 'on';
                    else
                        msgbox('PM app radio settings out of limits', 'PM Bootloader', 'error')
                    end
                else
                    app.APAddressEditField.Value = resp(3);
                    app.PMAddressEditField.Value = resp(2);
                    app.ChannelEditField.Value = resp(4);
                    app.TXPowerEditField.Value = resp(5);
                    
                    app.RadioWarningLabel.Visible = 'off';
                end
            else
                msgbox('Could not read PM app radio settings', 'PM Bootloader', 'error')
            end
        end

        % Button pushed function: WriteButton
        function WriteButtonPushed(app, event)
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            pageSize = 512;
            
            %sector 0 is radio bootloader, sector 17 is factory uart bootloader
            pagesPerSector  = [16, 16, 16, 16, 16, 16, 16, 128, 128, 16, 16, 16, 16, 16, 16, 16]; %sectors 1:16
            appStartAddress = 16*pageSize;
            sectorAddresses = [appStartAddress,  appStartAddress+cumsum(pagesPerSector)*pageSize]; %note: this assumes sector 0 is also 16 pages (sector 1 start = 0x2000)
            maxAppSector = 9;
            
            %If Write or Verify is selected, get the file before erasing
            if app.WriteCheckBox.Value || app.VerifyCheckBox.Value
                               
                %Get binary and parse into packets

                [filename, pathname] = uigetfile('*.bin', 'Select App File', 'MultiSelect', 'off');
                if isempty(pathname) || isempty(filename) || ~ischar(filename) || ~ischar(pathname)
                    msgbox('No file chosen', 'PM Bootloader', 'error');
                    app.StartButton.Text = 'Start';
                    return;
                else
                    fid = fopen([pathname filename]);
                    [binary, n] = fread(fid);
                    fclose(fid);

                    %PM bootloader only supports full pages, so pad the last page with zeros
                    nPages = ceil(n/pageSize);
                    nPadded = nPages*pageSize;
                    binaryPadded = zeros(nPadded, 1);
                    binaryPadded(1:n) = binary;
                    
                    %the final address in a sector is the first address of the next sector-1.
                    maxSector = find(sectorAddresses - appStartAddress > nPadded, 1)-1;
                    if maxSector > maxAppSector
                        resp = questdlg(sprintf('Sectors above %u are expected to be used for script storage and application data.  The selected binary extends into sector %u.  Do you want to write the binary?', maxAppSector, maxSector),...
                            'PM Bootloader');
                        if ~isequal(resp, 'Yes')
                            app.StartButton.Text = 'Start';
                            return;
                        end
                    end
                    
                    %JML packet sizes should be able to be larger, but >50 seems to fail
                    firstPacketSize = 12;  %firstPacketSize <= 52
                    packetSize = 50;       %packetSize <= 61-1
                    
                    if rem(pageSize-firstPacketSize, packetSize) ~= 0
                        error('packetSize*n + firstPacketSize ~= pageSize')
                    end
                    
                    nPacketsPerPage = (pageSize - firstPacketSize)/packetSize + 1;


                    packetLengthsPerPage = [firstPacketSize; ones(nPacketsPerPage-1, 1)*packetSize];
                    if maxSector > 1
                        pagesPerLastSector = (nPadded - (sectorAddresses(maxSector)-appStartAddress))/pageSize;
                    else
                        pagesPerLastSector = nPadded/pageSize;
                    end
                    
                    packets = cell(maxSector, 1);
                    for sector = 1:maxSector
                        fprintf('\nsector %u', sector)
                        if sector == maxSector
                            packetLengths = repmat(packetLengthsPerPage, pagesPerLastSector, 1);
                        else
                            packetLengths = repmat(packetLengthsPerPage, pagesPerSector(sector), 1);
                        end
                        if sector >1
                            startBinIndex = sectorAddresses(sector)-appStartAddress+1;
                        else
                            startBinIndex = 1;
                        end
                        
                        
                        endBinIndex = min(sectorAddresses(sector+1)-appStartAddress, nPadded); %last sector chunk may not go to end of sector
                        fprintf('  start %u   end %u', startBinIndex, endBinIndex)
                        packets{sector} = mat2cell(binaryPadded(startBinIndex:endBinIndex), packetLengths);
                    end
                    
                    totalPackets = 0;
                    for i=1:maxSector
                        totalPackets = totalPackets + length(packets{i});
                    end
                    
                end
            end
                        
            if app.EraseCheckBox.Value || app.BlankCheckCheckBox.Value
                
                %set the AP timeouts/retries appropriately
                if ~app.nnp.setRadio('rxTimeout', 251, 'retries', 1)
                    msgbox('Could not set AP timeout and retries', 'AP Configuration', 'error')
                end
                
                app.nnp.timeout = 2.5;
                
                if app.EraseCheckBox.Value
                    if app.EntireFlashCheckBox.Value
                        resp = app.nnp.pmboot('12', uint8([1 16]));
                    else
                        resp = app.nnp.pmboot('12', uint8([1 maxAppSector]));
                    end
                    if length(resp)==2 && resp(1) == hex2dec('22') && resp(2) == 1
                    else
                        msgbox('Erase failed', 'PM Bootloader', 'error');
                        return
                    end
                end
                if app.BlankCheckCheckBox.Value
                    if app.EntireFlashCheckBox.Value
                        resp = app.nnp.pmboot('18', uint8([1 16]));
                    else
                        resp = app.nnp.pmboot('18', uint8([1 maxAppSector]));
                    end
                    if length(resp)==4 && resp(1) == hex2dec('28')
                        if resp(2) ~= 0
                            msgbox(sprintf('Found non blank value (0x%02X) at offset 0x%02X, status: 0x%02X', resp(4), resp(3), resp(2)), 'PM Bootloader', 'error' );
                            return;
                        end
                    else
                        msgbox('Blank Check failed', 'PM Bootloader', 'error');
                        return
                    end
                end
            end
            
            if app.WriteCheckBox.Value || app.VerifyCheckBox.Value
                
                
                %set the AP timeouts/retries appropriately
                if ~app.nnp.setRadio('rxTimeout', 20, 'retries', 5)
                    msgbox('Could not set AP timeout and retries', 'AP Configuration', 'error')
                end
                app.nnp.timeout = 1.5;
                
                tic;
                if app.WriteCheckBox.Value
                    
                    h = waitbar(0, 'Writing App...');
                    totalPacketCount = 0;
                    
                    
                    for sector = maxSector:-1:1 % go in inverse order so first sector is last
                        fprintf('\nsector %u', sector)
                        address = sectorAddresses(sector);
                        
                        for i=1:length(packets{sector})
                            %fprintf('\npacket %u, %u', i, totalPacketCount)
                            if isvalid(h)
                                waitbar(totalPacketCount/totalPackets, h)
                            else
                                
                            end
                            totalPacketCount = totalPacketCount+1;
                            
                            while true
                                errStr = [];
                                if rem(i-1, nPacketsPerPage) == 0 %first packet of page
                                    
                                    addr = uint32(address);
                                    addrBytes = [rem(bitshift(addr, -24), 256), rem(bitshift(addr, -16), 256), rem(bitshift(addr, -8), 256), rem(addr, 256)];
                                    
                                    disp(addrBytes)
                                    
                                    pgszBytes = [floor(pageSize/256), rem(pageSize, 256)];
                                    resp = double(app.nnp.pmboot('10', uint8([addrBytes, pgszBytes, sector, packets{sector}{i}'])));
                                    if length(resp) == 2 && resp(1) == hex2dec('20')
                                        if resp(2) ~= 1
                                            errStr = '"Bad parameters" response to write command.  Make sure there are no interfering radios';
                                        else
                                            address = address + pageSize;
                                            break; % good response
                                        end
                                    else
                                        
                                        errStr =sprintf('Error writing first packet of page, sector %u, address %u ', sector, address);
                                        
                                    end
                                else
                                    resp = app.nnp.pmboot('11', uint8(packets{sector}{i}'));
                                    if length(resp) == 2 && resp(1) == hex2dec('21')
                                        if resp(2) ~= 1
                                            errStr = '"Bad parameters" response to write data.  Make sure there are no interfering radios';
                                        else
                                            break; % good response
                                        end
                                    else
                                        errStr = sprintf('Error writing packet #%u of page, sector %u, address %u ', rem(i-1, nPacketsPerPage), sector, address);
                                    end
                                end
                                %if we're here an error has occurred
                                resp = questdlg([errStr, ' Do you want to retry?'], 'PM Bootloader');
                                if ~isequal(resp, 'Yes')
                                    if isvalid(h)
                                        close(h)
                                    end
                                    return
                                end
                            end %end retry while loop
                        end
                    end
                end % end write
                if isvalid(h)
                    close(h)
                end
                toc;
                if app.VerifyCheckBox.Value
                    msgbox('Verify not yet implemented', 'PM Bootloader', 'help');
                end
            end
            
        end

        % Button pushed function: EnterBootloaderButton
        function EnterBootloaderButtonPushed(app, event)
            settings = struct('addrAP', hex2dec('BC'), 'addrPM', hex2dec('BC'), 'chan', 5, ...
                'txPower', 20, 'worInt', 0, 'rxTimeout', 50, 'retries', 5);
            settingsOut = app.nnp.setRadioSettings(settings);
            if ~isequal(settings, settingsOut)
                msgbox('Could not configure AP for radio communication with PM bootloader', 'AP Configuration', 'error')
            end
            
            resp = double(app.nnp.pmboot('14'));
            if length(resp) == 8 && resp(1) == hex2dec('24')
                app.enableAll(1);
                
                app.BootRevEditField.Value = sprintf('%u.%u', resp([3 2]));
                app.PMSerialEditField.Value = resp(6) + resp(7)*256;
                app.RestoreEditField.Value = resp(8);
                
                [app.PMRSSIEditField.Value, app.PMLQIEditField.Value] = app.calcRadio(resp(4), resp(5));
                
                app.APRSSIEditField.Value = app.nnp.RSSI;
                app.APLQIEditField.Value = app.nnp.LQI;
                
                app.EnterBootloaderButton.BackgroundColor = [0 1 0];
                app.EnterBootloaderButton.Text = {'In Bootloader Mode'; 'Press to update'; 'Bootloader Values'} ;
            else
                msgbox({'Could not enter PM bootloader.';...
                    '';...
                    'The PM Bootloader is only accessible within 15s after powering the PM.';...
                    '';...
                    'Power cycle the PM and try again:';...
                    '   If the PM is running its application and the';...
                    '   AP radio settings match the PM, use "Attempt PM Reset"';
                    '';...
                    '   Otherwise use the magnet reset or disconnect power supply';...
                    '';...
                    'The PM Bootloader requires VREC (coil power)'},...
                    'PM Bootloader', 'help');
            end
            
            
        end

        % Button pushed function: ExitBootloaderButton
        function ExitBootloaderButtonPushed(app, event)
            waittime = 18;
            
            
            resp = double(app.nnp.pmboot('15'));
            if length(resp)==1 && resp == hex2dec('25')
                %start timer progressbar here
                app.enableAll(0);
                app.EnterBootloaderButton.BackgroundColor = [0.96 0.96 0.96];
                app.EnterBootloaderButton.Text = 'Enter Bootloader';
                
                h=waitbar(0, 'Waiting for PM Application to Start...');
                for i = 1:waittime
                    if isvalid(h)
                        waitbar(i/waittime, h)
                        pause(1);
                    end
                end
                if isvalid(h)
                    close(h);
                end
                
            else
                msgbox('Could not confirm initiating PM bootloader exit', 'PM Bootloader', 'error')
            end
            
            %set radio back
            app.restoreRadio();
            app.ReadAppStatusPushed();
        end

        % Button pushed function: AttemptPMResetButton
        function AttemptPMResetButtonPushed(app, event)
            if app.nnp.nmt(7, '9E') == hex2dec('9E')
                %msgbox('Reset successful', 'PM Bootloader', 'help')
            else
                msgbox({'Could not trigger PM reset.';...
                    '';...
                    'If PM radio settings are unknown, use the magnet or disconnect power supply'},...
                    'PM Bootloader', 'help')
            end
        end

        % Button pushed function: SetAPButton
        function SetAPButtonPushed(app, event)
            app.savedSettings.addrAP = app.APAddressEditField.Value;
            app.savedSettings.addrPM = app.PMAddressEditField.Value;
            app.savedSettings.chan = app.ChannelEditField.Value;
            app.savedSettings.txPower = app.TXPowerEditField.Value;
            
            if app.ExitBootloaderButton.Enable == 'off'
                %only update the radio settings if not still in bootloader mode
                app.restoreRadio();
            else
                msgbox('Upon exiting bootloader mode, new AP settings will be used', 'AP Configuration', 'help')
            end
            
        end

        % Close request function: PowerModuleBootloaderUIFigure
        function PowerModuleBootloaderUIFigureCloseRequest(app, event)
            if isequal(app.ExitBootloaderButton.Enable, 'on')
                resp = questdlg('PM is still in bootloader mode.  Do you want to start the PM application and restore AP radio setttings','PM Bootloader');
                if isequal(resp, 'Yes')
                    app.ExitBootloaderButtonPushed(event);
                elseif ~isequal(resp, 'No')
                    return;
                end
                
            end
            delete(app)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PowerModuleBootloaderUIFigure = uifigure;
            app.PowerModuleBootloaderUIFigure.Name = 'Power Module Bootloader';
            app.PowerModuleBootloaderUIFigure.CloseRequestFcn = createCallbackFcn(app, @PowerModuleBootloaderUIFigureCloseRequest, true);
            app.HelpMenu = uimenu(app.PowerModuleBootloaderUIFigure);
            app.HelpMenu.Text = 'Help';
            app.ReadAppStatus = uibutton(app.PowerModuleBootloaderUIFigure, 'push');
            app.ReadAppStatus.ButtonPushedFcn = createCallbackFcn(app, @ReadAppStatusPushed, true);
            app.ReadAppStatus.Text = 'Read App Status';
            app.PowerModuleFlashPanel = uipanel(app.PowerModuleBootloaderUIFigure);
            app.PowerModuleFlashPanel.Title = 'Power Module Flash';
            app.StartButton = uibutton(app.PowerModuleFlashPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.EraseCheckBox = uicheckbox(app.PowerModuleFlashPanel);
            app.EraseCheckBox.Text = 'Erase';
            app.EraseCheckBox.Value = true;
            app.BlankCheckCheckBox = uicheckbox(app.PowerModuleFlashPanel);
            app.BlankCheckCheckBox.Text = 'BlankCheck';
            app.BlankCheckCheckBox.Value = true;
            app.VerifyCheckBox = uicheckbox(app.PowerModuleFlashPanel);
            app.VerifyCheckBox.Text = 'Verify';
            app.VerifyCheckBox.Value = true;
            app.WriteCheckBox = uicheckbox(app.PowerModuleFlashPanel);
            app.WriteCheckBox.Text = 'Write';
            app.WriteCheckBox.Value = true;
            app.EntireFlashCheckBox = uicheckbox(app.PowerModuleFlashPanel);
            app.EntireFlashCheckBox.Text = 'Entire Flash';
            app.EntireFlashCheckBox.Value = true;
            app.IncludingscriptsandappdataLabel = uilabel(app.PowerModuleFlashPanel);
            app.IncludingscriptsandappdataLabel.Text = {'(Including scripts'; '  and app data)'};
            app.PMApplicationRadioSettingsPanel = uipanel(app.PowerModuleBootloaderUIFigure);
            app.PMApplicationRadioSettingsPanel.Title = 'PM Application Radio Settings';
            app.APAddressEditFieldLabel = uilabel(app.PMApplicationRadioSettingsPanel);
            app.APAddressEditFieldLabel.Text = 'AP Address';
            app.APAddressEditField = uieditfield(app.PMApplicationRadioSettingsPanel, 'numeric');
            app.APAddressEditField.Limits = [1 254];
            app.APAddressEditField.Value = 3;
            app.PMAddressEditFieldLabel = uilabel(app.PMApplicationRadioSettingsPanel);
            app.PMAddressEditFieldLabel.Text = 'PM Address';
            app.PMAddressEditField = uieditfield(app.PMApplicationRadioSettingsPanel, 'numeric');
            app.PMAddressEditField.Limits = [1 254];
            app.PMAddressEditField.Value = 4;
            app.ChannelEditFieldLabel = uilabel(app.PMApplicationRadioSettingsPanel);
            app.ChannelEditFieldLabel.Text = 'Channel';
            app.ChannelEditField = uieditfield(app.PMApplicationRadioSettingsPanel, 'numeric');
            app.ChannelEditField.Limits = [0 9];
            app.ChannelEditField.Value = 5;
            app.TXPowerEditFieldLabel = uilabel(app.PMApplicationRadioSettingsPanel);
            app.TXPowerEditFieldLabel.Text = 'TX Power';
            app.TXPowerEditField = uieditfield(app.PMApplicationRadioSettingsPanel, 'numeric');
            app.TXPowerEditField.Limits = [0 46];
            app.TXPowerEditField.Value = 20;
            app.WriteButton = uibutton(app.PMApplicationRadioSettingsPanel, 'push');
            app.WriteButton.ButtonPushedFcn = createCallbackFcn(app, @WriteButtonPushed, true);
            app.WriteButton.Text = 'Write';
            app.ReadButton = uibutton(app.PMApplicationRadioSettingsPanel, 'push');
            app.ReadButton.ButtonPushedFcn = createCallbackFcn(app, @ReadButtonPushed, true);
            app.ReadButton.Text = 'Read';
            app.SetAPButton = uibutton(app.PMApplicationRadioSettingsPanel, 'push');
            app.SetAPButton.ButtonPushedFcn = createCallbackFcn(app, @SetAPButtonPushed, true);
            app.SetAPButton.Text = 'Set AP';
            app.RadioWarningLabel = uilabel(app.PMApplicationRadioSettingsPanel);
            app.RadioWarningLabel.Text = {'PM Settings have not been read'; 'AP Settings are displayed!'};
            app.PMSerialEditFieldLabel = uilabel(app.PowerModuleBootloaderUIFigure);
            app.PMSerialEditFieldLabel.Text = 'PM Serial#';
            app.PMSerialEditField = uieditfield(app.PowerModuleBootloaderUIFigure, 'numeric');
            app.PMSerialEditField.ValueDisplayFormat = '%5u';
            app.PMSerialEditField.Editable = 'off';
            app.RestoreODDefaultsButton = uibutton(app.PowerModuleBootloaderUIFigure, 'push');
            app.RestoreODDefaultsButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreODDefaultsButtonPushed, true);
            app.RestoreODDefaultsButton.Text = 'Restore OD Defaults';
            app.RSSIEditFieldLabel = uilabel(app.PowerModuleBootloaderUIFigure);
            app.RSSIEditFieldLabel.Text = 'RSSI';
            app.APRSSIEditField = uieditfield(app.PowerModuleBootloaderUIFigure, 'numeric');
            app.APRSSIEditField.ValueDisplayFormat = '%3.0f';
            app.APRSSIEditField.Editable = 'off';
            app.LQIEditFieldLabel = uilabel(app.PowerModuleBootloaderUIFigure);
            app.LQIEditFieldLabel.Text = 'LQI';
            app.APLQIEditField = uieditfield(app.PowerModuleBootloaderUIFigure, 'numeric');
            app.APLQIEditField.ValueDisplayFormat = '%u';
            app.APLQIEditField.Editable = 'off';
            app.EnterBootloaderButton = uibutton(app.PowerModuleBootloaderUIFigure, 'push');
            app.EnterBootloaderButton.ButtonPushedFcn = createCallbackFcn(app, @EnterBootloaderButtonPushed, true);
            app.EnterBootloaderButton.Text = 'Enter Bootloader';
            app.ExitBootloaderButton = uibutton(app.PowerModuleBootloaderUIFigure, 'push');
            app.ExitBootloaderButton.ButtonPushedFcn = createCallbackFcn(app, @ExitBootloaderButtonPushed, true);
            app.ExitBootloaderButton.Text = 'Exit Bootloader';
            app.AttemptPMResetButton = uibutton(app.PowerModuleBootloaderUIFigure, 'push');
            app.AttemptPMResetButton.ButtonPushedFcn = createCallbackFcn(app, @AttemptPMResetButtonPushed, true);
            app.AttemptPMResetButton.Text = 'Attempt PM Reset';
            app.RestoreEditFieldLabel = uilabel(app.PowerModuleBootloaderUIFigure);
            app.RestoreEditFieldLabel.Text = 'Restore';
            app.RestoreEditField = uieditfield(app.PowerModuleBootloaderUIFigure, 'numeric');
            app.RestoreEditField.Editable = 'off';
            app.TempCLabel = uilabel(app.PowerModuleBootloaderUIFigure);
            app.TempCLabel.Text = 'Temp ( °C)';
            app.TempEditField = uieditfield(app.PowerModuleBootloaderUIFigure, 'numeric');
            app.TempEditField.Editable = 'off';
            app.PMLQIEditField = uieditfield(app.PowerModuleBootloaderUIFigure, 'numeric');
            app.PMLQIEditField.ValueDisplayFormat = '%u';
            app.PMLQIEditField.Editable = 'off';
            app.PMRSSIEditField = uieditfield(app.PowerModuleBootloaderUIFigure, 'numeric');
            app.PMRSSIEditField.ValueDisplayFormat = '%3.0f';
            app.PMRSSIEditField.Editable = 'off';
            app.APLabel = uilabel(app.PowerModuleBootloaderUIFigure);
            app.APLabel.Text = 'AP';
            app.PMLabel = uilabel(app.PowerModuleBootloaderUIFigure);
            app.PMLabel.Text = 'PM';
            app.BootRevEditField_2Label = uilabel(app.PowerModuleBootloaderUIFigure);
            app.BootRevEditField_2Label.Text = 'BootRev';
            app.BootRevEditField = uieditfield(app.PowerModuleBootloaderUIFigure, 'text');
            app.BootRevEditField.Editable = 'off';
            app.AppRevEditFieldLabel = uilabel(app.PowerModuleBootloaderUIFigure);
            app.AppRevEditFieldLabel.Text = 'AppRev';
            app.AppRevEditField = uieditfield(app.PowerModuleBootloaderUIFigure, 'text');
            app.AppRevEditField.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = pmbootloader(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PowerModuleBootloaderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PowerModuleBootloaderUIFigure)
        end
    end
end