classdef profiler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StimulationProfilerUIFigure  matlab.ui.Figure
        PWusSliderLabel              matlab.ui.control.Label
        PWSlider                     matlab.ui.control.Slider
        PAmALabel                    matlab.ui.control.Label
        PASlider                     matlab.ui.control.Slider
        UITable                      matlab.ui.control.Table
        ClearAllHistoryButton        matlab.ui.control.Button
        PrevChButton                 matlab.ui.control.Button
        NextChButton                 matlab.ui.control.Button
        PrevNodeButton               matlab.ui.control.Button
        NextNodeButton               matlab.ui.control.Button
        ChEditFieldLabel             matlab.ui.control.Label
        ChEditField                  matlab.ui.control.NumericEditField
        NodeEditFieldLabel           matlab.ui.control.Label
        NodeEditField                matlab.ui.control.NumericEditField
        StimOnButton                 matlab.ui.control.Button
        StimOffButton                matlab.ui.control.Button
        AddButton                    matlab.ui.control.Button
        PWEditField                  matlab.ui.control.NumericEditField
        PAEditField                  matlab.ui.control.NumericEditField
        ErrorLampLabel               matlab.ui.control.Label
        ErrorLamp                    matlab.ui.control.Lamp
        ActionButtonGroup            matlab.ui.container.ButtonGroup
        PWButton                     matlab.ui.control.RadioButton
        PAButton                     matlab.ui.control.RadioButton
        RemoteTunerCheckBox          matlab.ui.control.CheckBox
        ErrorEditField               matlab.ui.control.EditField
        RemoveUncheckedButton        matlab.ui.control.Button
        AllCheckBox                  matlab.ui.control.CheckBox
        SavetoFileButton             matlab.ui.control.Button
        LoadfromFileButton           matlab.ui.control.Button
        SortButton                   matlab.ui.control.Button
        PWDnButton                   matlab.ui.control.Button
        PWUpButton                   matlab.ui.control.Button
        PAUpButton                   matlab.ui.control.Button
        PADnButton                   matlab.ui.control.Button
        incrementEditFieldLabel      matlab.ui.control.Label
        PAincEditField               matlab.ui.control.NumericEditField
        incrementEditFieldLabel_2    matlab.ui.control.Label
        PWincEditField               matlab.ui.control.NumericEditField
        RegTableCheckBox             matlab.ui.control.CheckBox
        RegCheckBox                  matlab.ui.control.CheckBox
        RegEditField                 matlab.ui.control.EditField
        RegButton                    matlab.ui.control.Button
        ZeroPWButton                 matlab.ui.control.Button
        ResetPWLabel                 matlab.ui.control.Label
    end


    properties (Access = public)
        nnp=[]; 
    end

    properties (Access = private)
        stimOn = false; % Description
    end

    methods (Access = private)
    
        function resetStim(app, varargin)
            if nargin == 2
                forceOff = varargin{1};
            else
                forceOff = false;
            end
                
            
            %if the PW and PA are both non zero, turn off Stim, before swithcing channels.  
            % Otherwise, leave everything as is.  If stim is turned off, highlight the non-zero
            % values so operator is reminded to set them appropriately before turning stim back on
            % use Force off between nodes
            if app.stimOn && (forceOff || (app.PWEditField.Value ~= 0 && app.PAEditField.Value ~= 0)) 
                StimOffButtonPushed(app, 0);
                
                %Highlight the non-zero values
                if app.PWEditField.Value ~= 0
                    app.PWEditField.BackgroundColor = [1, 1, 0];
                end
                if app.PAEditField.Value ~= 0
                    app.PAEditField.BackgroundColor = [1, 1, 0];
                end
            end
        end
        
    
        function nodeErr = setStimWithTable(app) 
            dataAll = app.UITable.Data;
            nodeErr = 0;
            
            if size(dataAll, 1)>0
                active = cell2mat(dataAll(:,2));
                data = dataAll(active==true,:);
                n = size(data, 1);
                node = cell2mat(data(:,3));
                ch = cell2mat(data(:,4));
                pw = cell2mat(data(:,5));
                pa = cell2mat(data(:,6));
                reg = nan(size(node));
                
                if size(unique([node ch], 'rows'),1)~=n
                    msgbox('A single Node/Channel has multiple active rows in the History Table.  Only the final entry will be applied', 'Profiler', 'warn')
                end
                if any(node==app.NodeEditField.Value & ch==app.ChEditField.Value)
                   msgbox('One of the Node/Channel from History Table matches the current Node/Channel.  The values from the table will be ignored.', 'Profiler', 'warn') 
                end
                for i=1:n
                    result = app.nnp.write(node(i), '3212', ch(i), uint8([pw(i) pa(i)]));
                    if isequal(result, 0)
                        
                        app.clearError();
                    else
                        nodeErr = node;
                        app.setError();
                        return;
                    end
                end
                
                if app.RegTableCheckBox.Value
                    for u = unique(node)'
                        result = app.nnp.write(u, '3210', 5, uint8([3 3 3 3]));
                        if isequal(result, 0)
                            reg(node==u)=3;
                        else
                            warning('could not set reg status to 3')
                        end
    
                    end
                    data(:,7) = mat2cell(reg,ones(n,1));
                    app.UITable.Data(active,:) = data;
                end
            end
                
            error = app.setStim();
            if error
                nodeErr = app.NodeEditField.Value;
            end
        end
    
        function error = setStim(app)
            app.PWEditField.BackgroundColor = [1, 1, 1];
            app.PAEditField.BackgroundColor = [1, 1, 1];
            
            node = app.NodeEditField.Value;
            ch = app.ChEditField.Value;
            pw = app.PWEditField.Value;
            pa = round(app.PAEditField.Value*10);
            
            result = app.nnp.write(node, '3212', ch, uint8([pw pa]));
            if isequal(result, 0)
               error = 0;
               app.clearError();
            else
                error = 1;
                app.setError();
                %indicate that values may not have updated
                app.PWEditField.BackgroundColor = [1, 0, 0];
                app.PAEditField.BackgroundColor = [1, 0, 0];
            end
            
            if app.RegCheckBox.Value
                result = app.nnp.write(node, '3210', 5, uint8([3 3 3 3]));
                if isequal(result, 0)
                    app.RegEditField.Value = 'Cleared Status. Press Update';
                else
                    warning('could not set reg status to 3')
                end
            end
        end
        
        function clearError(app)
            app.ErrorLamp.Color = [0.9 0.9 0.9];
            app.ErrorEditField.Value = '';
        end
        
        function setError(app)
            app.ErrorLamp.Color = [1 0 0];
            app.ErrorEditField.Value = app.nnp.lastError;
            %TODO:trigger light turning off after 1 s
        end
        
        function enableHistoryUIs(app, enable)
            %app.UITable.Enable = enable;
            if isequal(enable, 'off')
               app.UITable.ColumnEditable = [false, false, false, false, false, false, false, true];
            else
                app.UITable.ColumnEditable = [false, true, true, true, true, true, false, true];
            end
                
            
            app.AllCheckBox.Enable = enable;
            app.ClearAllHistoryButton.Enable = enable;
            app.RemoveUncheckedButton.Enable = enable;
            app.SavetoFileButton.Enable = enable;
            app.LoadfromFileButton.Enable = enable;
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, NNP)
            if isempty(NNP)
                app.nnp = NNPAPI();
                if isempty(app.nnp.port)
                    msgbox('No port chosen for NNP Access Point', 'Profiler', 'error')
                    delete(app.nnp)
                    return
                end
            else
                app.nnp = NNP;
            end
            StimOffButtonPushed(app, 0);
            
            app.RemoteTunerCheckBox.Visible = false;
            app.ActionButtonGroup.Visible = false;
            
            RegCheckBoxValueChanged(app, 0);
        end

        % Button pushed function: PrevChButton
        function PrevChButtonPushed(app, event)
            ch = app.ChEditField.Value;
            if ch>1
                app.ChEditField.Value = ch-1;
            end
            app.resetStim();
        end

        % Button pushed function: NextChButton
        function NextChButtonPushed(app, event)
            ch = app.ChEditField.Value;
            if ch<4
                app.ChEditField.Value = ch+1;
            end
            app.resetStim();
        end

        % Button pushed function: PrevNodeButton
        function PrevNodeButtonPushed(app, event)
            node = app.NodeEditField.Value;
            if node>1
                app.NodeEditField.Value = node-1;
            end
            app.resetStim(true); %force stim off
        end

        % Button pushed function: NextNodeButton
        function NextNodeButtonPushed(app, event)
            node = app.NodeEditField.Value;
            if node<127
                app.NodeEditField.Value = node+1;
            end
            app.resetStim(true); %force stim off
        end

        % Button pushed function: StimOnButton
        function StimOnButtonPushed(app, event)
            retry = 0;
            nodeErr=-1;
            while(nodeErr)
                nodeErr = app.setStimWithTable();
                retry = retry+1;
                if retry == 3
                    [modes, ~, ~, net] = app.nnp.getStatus();
                    if net==0
                        resp = questdlg('Network not On.  Turn on network?', 'Profiler');
                        if isequal(resp, 'Yes')
                            if ~app.nnp.networkOn()
                                msgbox('Could not turn on Network', 'Profiler', 'error');
                            end
                        end
                    elseif net==1
                        if ~isequal(modes(nodeErr), 'Waiting')
                            resp = questdlg(['Node(s) ' num2str(nodeErr') ' is not online and in waiting mode.  Cycle Network?'], 'Profiler');
                            if isequal(resp, 'Yes')
                                if ~app.nnp.networkOff()
                                    msgbox('Could not turn off Network', 'Profiler', 'error');
                                end
                                if ~app.nnp.networkOn()
                                    msgbox('Could not turn on Network', 'Profiler', 'error');
                                end
                            end
                        else
                            msgbox(['Node(s) ' num2str(nodeErr') ' is online and in waiting mode, but could not be set'], 'Profiler', 'error');
                        end
                    else
                         msgbox('Network status could not be determined', 'Profiler', 'error');
                    end
                    return;
                end
            end
            if app.nnp.enterTestStim
                app.stimOn = true;
                app.StimulationProfilerUIFigure.Color = [0.7, 1, 0.7];
                app.StimOnButton.BackgroundColor = [0.64,0.64,0.64];
                app.StimOffButton.BackgroundColor = [1,0.5,0.5];
                app.enableHistoryUIs('off');
            else
                app.setError();
            end
        end

        % Button pushed function: StimOffButton
        function StimOffButtonPushed(app, event)
            if app.nnp.enterWaiting
                app.stimOn = false;
                app.StimulationProfilerUIFigure.Color = [0.94,0.94,0.94];
                app.StimOffButton.BackgroundColor = [0.64,0.64,0.64];
                app.StimOnButton.BackgroundColor = [0.7,1,0.7];
                app.enableHistoryUIs('on');
            else
                app.setError();
            end       
        end

        % Value changed function: PASlider
        function PASliderValueChanged(app, event)
            value = round(app.PASlider.Value*10)/10;
            app.PASlider.Value = value;
            app.PAEditField.Value = value;
            %apply
            app.setStim();
        end

        % Value changed function: PWSlider
        function PWSliderValueChanged(app, event)
            value = round(app.PWSlider.Value);
            app.PWSlider.Value = value;
            app.PWEditField.Value = value;
            %apply
            app.setStim();
        end

        % Value changed function: PAEditField
        function PAEditFieldValueChanged(app, event)
            value = round(app.PAEditField.Value*10)/10;
            app.PAEditField.Value = value;
            app.PASlider.Value = value;
            %apply
            app.setStim();
        end

        % Value changed function: PWEditField
        function PWEditFieldValueChanged(app, event)
            %Edit field forces rounded value
            value = app.PWEditField.Value;
            app.PWSlider.Value = value;
            %apply
            app.setStim();
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            ch = app.ChEditField.Value;
            node = app.NodeEditField.Value;
            pw = app.PWEditField.Value;
            pa = app.PAEditField.Value*10;
            notes = inputdlg('Notes:');
            data = app.UITable.Data;
            data = [data; {[],false, node, ch, pw, pa, '',notes{1}}];
            app.UITable.Data = data;
        end

        % Button pushed function: ClearAllHistoryButton
        function ClearAllHistoryButtonPushed(app, event)
            resp = questdlg('Are you sure you want to clear the history table?', 'Profiler History');
            if isequal(resp, 'Yes')
                app.UITable.Data = cell(0,8);
            end
        end

        % Button pushed function: RemoveUncheckedButton
        function RemoveUncheckedButtonPushed(app, event)
            data = app.UITable.Data;
            if size(data, 1)>0
                active = cell2mat(data(:,2));
                data = data(active==true,:);
                app.UITable.Data = data;
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            row = event.Indices(1);
            col = event.Indices(2);
            
            val = str2double(event.EditData);
            switch col
                case 3 %node
                    if ~ismember(val, 1:127)
                        app.UITable.Data(row, col) = {event.PreviousData};
                    else
                        app.UITable.Data(row, col) = {val};
                    end
                case 4 %ch
                    if ~ismember(val, 1:4)
                        app.UITable.Data(row, col)  = {event.PreviousData};
                    else
                        app.UITable.Data(row, col) = {val};
                    end
                case 5 %pw
                    if ~ismember(val, 0:255)
                        app.UITable.Data(row, col)  = {event.PreviousData};
                    else
                        app.UITable.Data(row, col) = {val};
                    end
                case 6 %pa
                    if ~ismember(val, 0:200)
                        app.UITable.Data(row, col)  = {event.PreviousData};
                    else
                        app.UITable.Data(row, col) = {val};
                    end
            end                   
        end

        % Value changed function: NodeEditField
        function NodeEditFieldValueChanged(app, event)
            app.resetStim();
        end

        % Value changed function: ChEditField
        function ChEditFieldValueChanged(app, event)
            app.resetStim();
        end

        % Value changed function: AllCheckBox
        function AllCheckBoxValueChanged(app, event)
            value = app.AllCheckBox.Value;
            data = app.UITable.Data;
            n = size(data, 1);
            if n>0
                if value == 0
                    data(:,2) = mat2cell(false(n,1), ones(n,1));
                else
                    data(:,2) = mat2cell(true(n,1), ones(n,1));
                end
                app.UITable.Data = data;
            end
                
        end

        % Button pushed function: SavetoFileButton
        function SavetoFileButtonPushed(app, event)
            [filename, filepath] = uiputfile('.mat','Save Profiler History');
            if filename
                data = app.UITable.Data;
                save([filepath filename], 'data');
            end
        end

        % Button pushed function: LoadfromFileButton
        function LoadfromFileButtonPushed(app, event)
            [filename, filepath] = uigetfile('.mat','Select Profiler History file');
            if filename
                ld = load([filepath filename]);
                data = ld.data;
                %Error checking on input file
                if size(data, 2) ~= 8
                    msgbox('Profiler history file does not have 8 columns of data', 'History File', 'error')
                    return
                end
                if size(data,1) == 0
                    msgbox('Profiler history file contains no rows', 'History File', 'error')
                    return
                end
                selector = cell2mat(data(:,1));
                if ~isempty(selector)
                    msgbox('Column 1 (Selector) must be all empty', 'History File', 'error')
                    return
                end
                active = cell2mat(data(:,2));
                if ~islogical(active)
                    msgbox('Column 2 (Active) must be all logicals', 'History File', 'error')
                    return
                end
                node = cell2mat(data(:,3));
                if any(~ismember(node, 1:127))
                    msgbox('Column 3 (Node) must be 1-127', 'History File', 'error')
                    return
                end
                ch = cell2mat(data(:,4));
                if any(~ismember(ch, 1:4))
                    msgbox('Column 4 (Ch) must be 1-4', 'History File', 'error')
                    return
                end
                pw = cell2mat(data(:,5));
                if any(~ismember(pw, 0:255))
                    msgbox('Column 5 (PW) must be 0-255', 'History File', 'error')
                    return
                end
                pa = cell2mat(data(:,6));
                if any(~ismember(pa, 0:200))
                    msgbox('Column 6 (PA) must be 0-200', 'History File', 'error')
                    return
                end
                app.UITable.Data = data;
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            row = event.Indices(1);
            col = event.Indices(2); 
            
            if col == 1 && app.stimOn == false
                data = app.UITable.Data(row,:);
                node = data{3};
                ch = data{4};
                pw = data{5};
                pa = data{6};
                
                app.NodeEditField.Value = node;
                app.ChEditField.Value = ch;
                app.PASlider.Value = pa/10;
                app.PAEditField.Value = pa/10;
                app.PASlider.Value = pa/10;
                app.PWEditField.Value = pw;
                app.PWSlider.Value = pw;
            end
        end

        % Value changed function: PAincEditField
        function PAincEditFieldValueChanged(app, event)
            value = round(app.PAincEditField.Value*10)/10;
            app.PAincEditField.Value = value;
        end

        % Button pushed function: PAUpButton
        function PAUpButtonPushed(app, event)
            inc = app.PAincEditField.Value;
            value = app.PAEditField.Value + inc;
            if value > 20
                value = 20;
            end
            app.PAEditField.Value = value;
            app.PASlider.Value = value;
             %apply
            app.setStim();
        end

        % Button pushed function: PADnButton
        function PADnButtonPushed(app, event)
            inc = app.PAincEditField.Value;
            value = app.PAEditField.Value - inc;
            if value < 0
                value = 0;
            end
            app.PAEditField.Value = value;
            app.PASlider.Value = value;
            %apply
            app.setStim();
        end

        % Button pushed function: PWDnButton
        function PWDnButtonPushed(app, event)
            inc = app.PWincEditField.Value;
            value = app.PWEditField.Value - inc;
            if value < 0
                value = 0;
            end
            app.PWEditField.Value = value;
            app.PWSlider.Value = value;
            %apply
            app.setStim();
        end

        % Button pushed function: PWUpButton
        function PWUpButtonPushed(app, event)
            inc = app.PWincEditField.Value;
            value = app.PWEditField.Value + inc;
            if value > 255
                value = 255;
            end
            app.PWEditField.Value = value;
            app.PWSlider.Value = value;
            %apply
            app.setStim();
        end

        % Button pushed function: SortButton
        function SortButtonPushed(app, event)
            data = app.UITable.Data;
            n = size(data, 1);
            if n >0
                node = cell2mat(data(:,3));
                ch = cell2mat(data(:,4));
                
                [~, row] = sortrows([node ch], [1 2]); %sort by node first and ch second. Get index of sorted rows
                
                app.UITable.Data = data(row,:);
            end
        end

        % Value changed function: RegCheckBox
        function RegCheckBoxValueChanged(app, event)
            if app.RegCheckBox.Value
                visible = 'on';
            else
                visible = 'off';
            end
            
            app.RegTableCheckBox.Visible=visible;
            app.RegEditField.Visible=visible;
            app.RegButton.Visible=visible;
            
        end

        % Button pushed function: RegButton
        function RegButtonPushed(app, event)
            if app.RegCheckBox.Value
                node = app.NodeEditField.Value;
                ch = app.ChEditField.Value;
                
                result = app.nnp.read(node, '3210', 5, 'uint8');
                if length(result) == 4
                    switch result(ch)
                        case 0, app.RegEditField.Value = '0: Out of Regulation!';
                        case 1, app.RegEditField.Value = '1: In Regulation';
                        case 2, app.RegEditField.Value = '2: Pulse<10us';
                        case 3, app.RegEditField.Value = '3: No pulse yet';
                        otherwise, app.RegEditField.Value = [num2str(result(ch)) ': ?'];  
                    end
                else
                    app.RegEditField.Value = 'Could not read';  
                end
                
            end
            if app.RegTableCheckBox.Value
                
                 dataAll = app.UITable.Data;
   
                 if size(dataAll, 1)>0
                    active = cell2mat(dataAll(:,2));
                    data = dataAll(active==true,:);
                    n = size(data, 1);
                    node = cell2mat(data(:,3));
                    ch = cell2mat(data(:,4));
                    reg = nan(size(node));
                    
                    for u = unique(node)'
                        result = app.nnp.read(u, '3210', 5, 'uint8');
                        if length(result) == 4
                            reg(node==u)=result(ch(node==u));
                        else
                            warning('Could not read reg status for table')
                        end
                    end
                    
                    data(:,7) = mat2cell(reg,ones(n,1));
                    app.UITable.Data(active,:) = data;
                end
            end
        end

        % Button pushed function: ZeroPWButton
        function ZeroPWButtonPushed(app, event)
            app.PWSlider.Value = 0;
            app.PWEditField.Value=0;
             %apply
            app.setStim();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StimulationProfilerUIFigure = uifigure('Visible', 'off');
            app.StimulationProfilerUIFigure.Name = 'Stimulation Profiler';
            app.PWusSliderLabel = uilabel(app.StimulationProfilerUIFigure);
            app.PWusSliderLabel.Text = 'PW (us)';
            app.PWSlider = uislider(app.StimulationProfilerUIFigure);
            app.PWSlider.Limits = [0 255];
            app.PWSlider.MajorTicks = [0 20 40 60 80 100 120 140 160 180 200 220 240];
            app.PWSlider.MajorTickLabels = {'0', '20', '40', '60', '80', '100', '120', '140', '160', '180', '200', '220', '240'};
            app.PWSlider.ValueChangedFcn = createCallbackFcn(app, @PWSliderValueChanged, true);
            app.PWSlider.MinorTicks = [0 10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180 190 200 210 220 230 240 250];
            app.PAmALabel = uilabel(app.StimulationProfilerUIFigure);
            app.PAmALabel.Text = 'PA (mA)';
            app.PASlider = uislider(app.StimulationProfilerUIFigure);
            app.PASlider.Limits = [0 20];
            app.PASlider.ValueChangedFcn = createCallbackFcn(app, @PASliderValueChanged, true);
            app.PASlider.MinorTicks = [];
            app.PASlider.Value = 10;
            app.UITable = uitable(app.StimulationProfilerUIFigure);
            app.UITable.ColumnName = {'Select'; 'Active'; 'Node'; 'Ch'; 'PW'; 'PA'; 'Reg'; 'Notes'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = [false true true true true true false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.ClearAllHistoryButton = uibutton(app.StimulationProfilerUIFigure, 'push');
            app.ClearAllHistoryButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllHistoryButtonPushed, true);
            app.ClearAllHistoryButton.Text = 'Clear All History';
            app.PrevChButton = uibutton(app.StimulationProfilerUIFigure, 'push');
            app.PrevChButton.ButtonPushedFcn = createCallbackFcn(app, @PrevChButtonPushed, true);
            app.PrevChButton.Text = '◄';
            app.NextChButton = uibutton(app.StimulationProfilerUIFigure, 'push');
            app.NextChButton.ButtonPushedFcn = createCallbackFcn(app, @NextChButtonPushed, true);
            app.NextChButton.Text = '►';
            app.PrevNodeButton = uibutton(app.StimulationProfilerUIFigure, 'push');
            app.PrevNodeButton.ButtonPushedFcn = createCallbackFcn(app, @PrevNodeButtonPushed, true);
            app.PrevNodeButton.Text = '◄';
            app.NextNodeButton = uibutton(app.StimulationProfilerUIFigure, 'push');
            app.NextNodeButton.ButtonPushedFcn = createCallbackFcn(app, @NextNodeButtonPushed, true);
            app.NextNodeButton.Text = '►';
            app.ChEditFieldLabel = uilabel(app.StimulationProfilerUIFigure);
            app.ChEditFieldLabel.Text = 'Ch';
            app.ChEditField = uieditfield(app.StimulationProfilerUIFigure, 'numeric');
            app.ChEditField.Limits = [1 4];
            app.ChEditField.RoundFractionalValues = 'on';
            app.ChEditField.ValueDisplayFormat = '%.0f';
            app.ChEditField.ValueChangedFcn = createCallbackFcn(app, @ChEditFieldValueChanged, true);
            app.ChEditField.Value = 1;
            app.NodeEditFieldLabel = uilabel(app.StimulationProfilerUIFigure);
            app.NodeEditFieldLabel.Text = 'Node';
            app.NodeEditField = uieditfield(app.StimulationProfilerUIFigure, 'numeric');
            app.NodeEditField.Limits = [1 127];
            app.NodeEditField.RoundFractionalValues = 'on';
            app.NodeEditField.ValueDisplayFormat = '%.0f';
            app.NodeEditField.ValueChangedFcn = createCallbackFcn(app, @NodeEditFieldValueChanged, true);
            app.NodeEditField.Value = 1;
            app.StimOnButton = uibutton(app.StimulationProfilerUIFigure, 'push');
            app.StimOnButton.ButtonPushedFcn = createCallbackFcn(app, @StimOnButtonPushed, true);
            app.StimOnButton.Text = 'Stim On';
            app.StimOffButton = uibutton(app.StimulationProfilerUIFigure, 'push');
            app.StimOffButton.ButtonPushedFcn = createCallbackFcn(app, @StimOffButtonPushed, true);
            app.StimOffButton.Text = 'Stim Off';
            app.AddButton = uibutton(app.StimulationProfilerUIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add Current Channel to History';
            app.PWEditField = uieditfield(app.StimulationProfilerUIFigure, 'numeric');
            app.PWEditField.Limits = [0 255];
            app.PWEditField.RoundFractionalValues = 'on';
            app.PWEditField.ValueDisplayFormat = '%.0f';
            app.PWEditField.ValueChangedFcn = createCallbackFcn(app, @PWEditFieldValueChanged, true);
            app.PAEditField = uieditfield(app.StimulationProfilerUIFigure, 'numeric');
            app.PAEditField.Limits = [0 20];
            app.PAEditField.ValueDisplayFormat = '%.1f';
            app.PAEditField.ValueChangedFcn = createCallbackFcn(app, @PAEditFieldValueChanged, true);
            app.PAEditField.Value = 10;
            app.ErrorLampLabel = uilabel(app.StimulationProfilerUIFigure);
            app.ErrorLampLabel.Text = 'Error';
            app.ErrorLamp = uilamp(app.StimulationProfilerUIFigure);
            app.ActionButtonGroup = uibuttongroup(app.StimulationProfilerUIFigure);
            app.ActionButtonGroup.Title = 'Action';
            app.PWButton = uiradiobutton(app.ActionButtonGroup);
            app.PWButton.Text = 'PW';
            app.PWButton.Value = true;
            app.PAButton = uiradiobutton(app.ActionButtonGroup);
            app.PAButton.Text = 'PA';
            app.RemoteTunerCheckBox = uicheckbox(app.StimulationProfilerUIFigure);
            app.RemoteTunerCheckBox.Text = 'RemoteTuner';
            app.ErrorEditField = uieditfield(app.StimulationProfilerUIFigure, 'text');
            app.ErrorEditField.Editable = 'off';
            app.RemoveUncheckedButton = uibutton(app.StimulationProfilerUIFigure, 'push');
            app.RemoveUncheckedButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveUncheckedButtonPushed, true);
            app.RemoveUncheckedButton.Text = {'Remove Inactive'; 'from History'};
            app.AllCheckBox = uicheckbox(app.StimulationProfilerUIFigure);
            app.AllCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllCheckBoxValueChanged, true);
            app.AllCheckBox.Text = 'All';
            app.SavetoFileButton = uibutton(app.StimulationProfilerUIFigure, 'push');
            app.SavetoFileButton.ButtonPushedFcn = createCallbackFcn(app, @SavetoFileButtonPushed, true);
            app.SavetoFileButton.Text = 'Save to File';
            app.LoadfromFileButton = uibutton(app.StimulationProfilerUIFigure, 'push');
            app.LoadfromFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfromFileButtonPushed, true);
            app.LoadfromFileButton.Text = 'Load from File';
            app.SortButton = uibutton(app.StimulationProfilerUIFigure, 'push');
            app.SortButton.ButtonPushedFcn = createCallbackFcn(app, @SortButtonPushed, true);
            app.SortButton.Text = 'Sort';
            app.PWDnButton = uibutton(app.StimulationProfilerUIFigure, 'push');
            app.PWDnButton.ButtonPushedFcn = createCallbackFcn(app, @PWDnButtonPushed, true);
            app.PWDnButton.Text = '◄';
            app.PWUpButton = uibutton(app.StimulationProfilerUIFigure, 'push');
            app.PWUpButton.ButtonPushedFcn = createCallbackFcn(app, @PWUpButtonPushed, true);
            app.PWUpButton.Text = '►';
            app.PAUpButton = uibutton(app.StimulationProfilerUIFigure, 'push');
            app.PAUpButton.ButtonPushedFcn = createCallbackFcn(app, @PAUpButtonPushed, true);
            app.PAUpButton.Text = '▲';
            app.PADnButton = uibutton(app.StimulationProfilerUIFigure, 'push');
            app.PADnButton.ButtonPushedFcn = createCallbackFcn(app, @PADnButtonPushed, true);
            app.PADnButton.Text = '▼';
            app.incrementEditFieldLabel = uilabel(app.StimulationProfilerUIFigure);
            app.incrementEditFieldLabel.Text = 'increment';
            app.PAincEditField = uieditfield(app.StimulationProfilerUIFigure, 'numeric');
            app.PAincEditField.Limits = [0 5];
            app.PAincEditField.ValueDisplayFormat = '%.1f';
            app.PAincEditField.ValueChangedFcn = createCallbackFcn(app, @PAincEditFieldValueChanged, true);
            app.PAincEditField.Value = 1;
            app.incrementEditFieldLabel_2 = uilabel(app.StimulationProfilerUIFigure);
            app.incrementEditFieldLabel_2.Text = 'increment';
            app.PWincEditField = uieditfield(app.StimulationProfilerUIFigure, 'numeric');
            app.PWincEditField.Limits = [0 20];
            app.PWincEditField.RoundFractionalValues = 'on';
            app.PWincEditField.ValueDisplayFormat = '%.0f';
            app.PWincEditField.Value = 5;
            app.RegTableCheckBox = uicheckbox(app.StimulationProfilerUIFigure);
            app.RegTableCheckBox.Text = 'Reg';
            app.RegCheckBox = uicheckbox(app.StimulationProfilerUIFigure);
            app.RegCheckBox.ValueChangedFcn = createCallbackFcn(app, @RegCheckBoxValueChanged, true);
            app.RegCheckBox.Text = 'Regulation ';
            app.RegEditField = uieditfield(app.StimulationProfilerUIFigure, 'text');
            app.RegButton = uibutton(app.StimulationProfilerUIFigure, 'push');
            app.RegButton.ButtonPushedFcn = createCallbackFcn(app, @RegButtonPushed, true);
            app.RegButton.Text = {'Update'; 'Status'};
            app.ZeroPWButton = uibutton(app.StimulationProfilerUIFigure, 'push');
            app.ZeroPWButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroPWButtonPushed, true);
            app.ZeroPWButton.Text = '0';
            app.ResetPWLabel = uilabel(app.StimulationProfilerUIFigure);
            app.ResetPWLabel.Text = {'Reset'; 'PW'};
            % Show the figure after all components are created
            app.StimulationProfilerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = profiler(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StimulationProfilerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StimulationProfilerUIFigure)
        end
    end
end