classdef radioconfig < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RadioConfigurationUIFigure    matlab.ui.Figure
        HelpMenu                      matlab.ui.container.Menu
        PMSerialEditField             matlab.ui.control.NumericEditField
        PMSerialEditFieldLabel        matlab.ui.control.Label
        Lamp                          matlab.ui.control.Lamp
        AutoUpdateCheckBox            matlab.ui.control.CheckBox
        SaveAPsettingsButton          matlab.ui.control.Button
        PMLQIEditField                matlab.ui.control.NumericEditField
        PMEditField_2Label            matlab.ui.control.Label
        APLQIEditField                matlab.ui.control.NumericEditField
        APEditField_2Label            matlab.ui.control.Label
        PMRSSIEditField               matlab.ui.control.NumericEditField
        PMEditFieldLabel              matlab.ui.control.Label
        APRSSIEditField               matlab.ui.control.NumericEditField
        APEditFieldLabel              matlab.ui.control.Label
        AccessPointSettingsPanel      matlab.ui.container.Panel
        APRadioRetriesEditField       matlab.ui.control.NumericEditField
        APRadioretriesEditFieldLabel  matlab.ui.control.Label
        APTXPowerEditField            matlab.ui.control.NumericEditField
        APTXPowerEditFieldLabel       matlab.ui.control.Label
        APUSBTimeoutEditField         matlab.ui.control.NumericEditField
        APUSBtimeoutsEditFieldLabel   matlab.ui.control.Label
        APRadioTimeoutEditField       matlab.ui.control.NumericEditField
        APRadiotimeoutEditFieldLabel  matlab.ui.control.Label
        hint                          matlab.ui.control.Label
        PowerModuleSettingsPanel      matlab.ui.container.Panel
        PMTXPowerEditField            matlab.ui.control.NumericEditField
        PMTXPowerEditFieldLabel       matlab.ui.control.Label
        CommonSettingsPanel           matlab.ui.container.Panel
        IfcheckedchangeswillbeLabel   matlab.ui.control.Label
        ModifyPMCheckBox              matlab.ui.control.CheckBox
        WakeOnRadioCheckBox           matlab.ui.control.CheckBox
        ApplyButton                   matlab.ui.control.Button
        WOREditField                  matlab.ui.control.NumericEditField
        WakeintervalmsEditFieldLabel  matlab.ui.control.Label
        ChannelEditField              matlab.ui.control.NumericEditField
        ChannelEditFieldLabel         matlab.ui.control.Label
        PMAddressEditField            matlab.ui.control.NumericEditField
        PMAddressEditFieldLabel       matlab.ui.control.Label
        APAddressEditField            matlab.ui.control.NumericEditField
        APAddressEditFieldLabel       matlab.ui.control.Label
        LQIAxes                       matlab.ui.control.UIAxes
        RSSIAxes                      matlab.ui.control.UIAxes
    end


    properties (Access = public)
        nnp = []; 
        skipUpdate = false;
        updateTimer = [];
        nSamp = [];
        plotbuf = [];
        callingFigure=[]; %handle to calling figure
               
    end

    properties (Access = private)
        savedSettings = []; 
        retryCount = 0;
    end

    methods (Access = public)
    
        function updateDisplaysCallback(app, obj, event)
             if strcmp(app.RadioConfigurationUIFigure.WindowState,'minimized')
                 app.AutoUpdateCheckBox.Value=false;
                 app.RadioConfigurationUIFigureCloseRequest();
            end
            if app.AutoUpdateCheckBox.Value
                app.updateDisplays();
            else
                app.APRSSIEditField.Enable = 'off';
                app.APLQIEditField.Enable = 'off';
                app.PMRSSIEditField.Enable = 'off';
                app.PMLQIEditField.Enable = 'off';
                app.Lamp.Color = [.7 .7 .7];
            end
        end

        function updateDisplays(app)
            if app.skipUpdate 
                app.APRSSIEditField.Enable = 'off';
                app.APLQIEditField.Enable = 'off';
                app.PMRSSIEditField.Enable = 'off';
                app.PMLQIEditField.Enable = 'off';
                app.Lamp.Color = [.7 .7 .7];
                return;
            end
            
            str = {};
            
            app.PMAddressEditField.BackgroundColor = [1 1 1];
            app.APAddressEditField.BackgroundColor = [1 1 1];
            app.ChannelEditField.BackgroundColor = [1 1 1];
            app.WOREditField.BackgroundColor = [1 1 1];
            
            resp = app.nnp.read(7, '2600', 1, 6, 'uint8');
            if length(resp)==6 
                
                app.APRSSIEditField.Enable = 'on';
                app.APLQIEditField.Enable = 'on';
                app.PMRSSIEditField.Enable = 'on';
                app.PMLQIEditField.Enable = 'on';
                
                if resp(1) ~= app.PMAddressEditField.Value
                    str{length(str)+1} =  'PM address from PM does not match AP settings';
                end
                if resp(2) ~= app.APAddressEditField.Value
                    str{length(str)+1} = 'AP address from PM does not match AP settings';
                end
                if resp(3) ~= app.ChannelEditField.Value
                    str{length(str)+1} = 'AP address from PM does not match AP settings';
                end 
                
                app.PMTXPowerEditField.Value = double(resp(4));
                
                rssioffset = 74;
                rssiraw = double(resp(5));
                lqiraw = double(resp(6));
                
                 if rssiraw < 128
                     rssi = rssiraw/2 - rssioffset;
                 else
                     rssi = (rssiraw-256)/2 - rssioffset;
                 end
                 if lqiraw >= 128
                    lqi = lqiraw - 128;
                 else
                    lqi = lqiraw; %bad CRC
                    str{length(str)+1} = 'BadCRC on PM!';
                 end
                
                app.PMRSSIEditField.Value = rssi;
                app.PMLQIEditField.Value = lqi;
                
                if ~isempty(app.nnp.RSSI) 
                    app.APRSSIEditField.Value = app.nnp.RSSI;
                end
                if ~isempty(app.nnp.LQI)
                    app.APLQIEditField.Value = app.nnp.LQI;
                end
                
                app.plotbuf(1:end-1,:) = app.plotbuf(2:end,:);
                app.plotbuf(end,:) = [app.nnp.RSSI; rssi; app.nnp.LQI; lqi ];
                for i = 1:2
                    app.RSSIAxes.Children(i).YData = app.plotbuf(:,i); 
                    app.LQIAxes.Children(i).YData = app.plotbuf(:,i+2); 
                end 
                
                app.Lamp.Color = [0 1 0];
                drawnow;
                app.retryCount = 0;
            else
                app.APRSSIEditField.Enable = 'off';
                app.APLQIEditField.Enable = 'off';
                app.PMRSSIEditField.Enable = 'off';
                app.PMLQIEditField.Enable = 'off';
                
                app.Lamp.Color = [1 0 0];
                drawnow;
                app.retryCount = app.retryCount + 1;
                if app.retryCount >= 3
                    app.AutoUpdateCheckBox.Value = 0;
                    app.AutoUpdateCheckBoxValueChanged();
                end
                return;
            end
            
            resp = double(app.nnp.read(7, '2600', 9, 'uint16'));
            if length(resp)==1
                if resp(1) ~= app.WOREditField.Value && app.WakeOnRadioCheckBox.Value == 1
                    %str{length(str)+1} = sprintf('Wake Interval from PM is set to %u ms', resp(1));
                end
                if  resp(1) > 0 && app.WakeOnRadioCheckBox.Value == 0
                    app.WakeOnRadioCheckBox.Value = 1;
                    if ~app.nnp.setRadio('worInt', app.WOREditField.Value)
                        str{length(str)+1} = 'AP did not have Wake On Radio enabled, but PM does.  Attempted but failed to enabled WOR on AP';
                    else
                        str{length(str)+1} = 'AP did not have Wake On Radio enabled, but PM does.  WOR enabled on AP';
                    end
                elseif  resp(1) == 0 && app.WakeOnRadioCheckBox.Value == 1
                    str{length(str)+1} = 'PM does not have Wake On Radio enabled.  Turn off on AP for higher bandwidth';

                end
            else
                return;
            end
            
            resp = app.nnp.getSerial(7);
            if length(resp)==1
                app.PMSerialEditField.Value = resp;
            else
                app.PMSerialEditField.Value = Inf;
            end
            
            if ~isempty(str)
                msgbox(str, 'AP+PM Configuration.', 'help')   
                app.AutoUpdateCheckBox.Value = 0;
                 app.AutoUpdateCheckBoxValueChanged();
                %app.skipUpdate = true;
            end
            
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin<2
                NNP=[];
            else
                NNP=varargin{1};
            end
            if isempty(NNP)
                app.nnp = NNPAPI();
                if isempty(app.nnp.port)
                    msgbox('No port chosen for NNP Access Point')
                    delete(app.nnp)
                    return
                end
            else
                app.nnp = NNP;
            end
            if nargin>2
                app.callingFigure=varargin{2};
            end

            app.RadioConfigurationUIFigure.WindowStyle='alwaysontop';
            %read back AP settings
            settings = app.nnp.getRadioSettings();
            if isempty(settings)
                msgbox('could not read back AccessPoint Settings, Radio Configuration will close',...
                    'Radio Configuration', 'error'); 
                return;
            else
                app.savedSettings = settings;
                app.savedSettings.usbTimeout = app.nnp.timeout;
            end
            
            app.APAddressEditField.Value = settings.addrAP;
            app.PMAddressEditField.Value = settings.addrPM;
            app.ChannelEditField.Value   = settings.chan;
            app.APTXPowerEditField.Value = settings.txPower;
            app.APRadioTimeoutEditField.Value = settings.rxTimeout;
            app.APRadioRetriesEditField.Value = settings.retries;
            app.APUSBTimeoutEditField.Value   = app.nnp.timeout;
            
            if settings.worInt > 0
                app.WOREditField.Enable = 'on';
                app.WOREditField.Value = settings.worInt;
                app.WakeOnRadioCheckBox.Value = 1;
            else
                app.WOREditField.Enable = 'off';
                app.WakeOnRadioCheckBox.Value = 0;
            end
            
            
            app.nSamp = 100;
            app.plotbuf = zeros(app.nSamp,4);
            plot(app.RSSIAxes, 1:app.nSamp, app.plotbuf(:,1:2));
            plot(app.LQIAxes, 1:app.nSamp, app.plotbuf(:,3:4));
            app.RSSIAxes.XLim = [0 100];
            app.LQIAxes.XLim = [0 100];
            app.RSSIAxes.YLim = [-100 -20];
            app.LQIAxes.YLim = [40 60];
            app.RSSIAxes.Children(1).Color = [1 0 0]; %AP=red
            app.RSSIAxes.Children(2).Color = [0 0 1]; %PM=blue
            app.LQIAxes.Children(1).Color = [1 0 0]; %AP=red
            app.LQIAxes.Children(2).Color = [0 0 1]; %PM=blue
            
            app.updateDisplays();
            
%             if isequal(app.Lamp.Color, [0 1 0])
%                 app.AutoUpdateCheckBox.Value = 1;
%             end
            
            app.updateTimer = timer();
            app.updateTimer.Period = 0.4;
            app.updateTimer.ExecutionMode = 'fixedRate';
            app.updateTimer.BusyMode = 'drop';
            app.updateTimer.TimerFcn = @app.updateDisplaysCallback;
            
            start(app.updateTimer);
        end

        % Value changed function: APAddressEditField
        function APAddressEditFieldValueChanged(app, event)
            if ~app.ModifyPMCheckBox.Value 
                % prevent changes before completing current change
                app.APAddressEditField.Enable=false;
                app.PMAddressEditField.Enable=false;
                app.ChannelEditField.Enable=false;
                retry=true;
                while retry
                    if ~app.nnp.setRadio('addrAP', app.APAddressEditField.Value)
                        fig=app.RadioConfigurationUIFigure;
                        msg='Error writing AP address.  Retry?';
                        title= 'Retry Settings Update';
                        selection = uiconfirm(fig, msg,title,...
                            'Options',{'Retry','Cancel'},...
                            'DefaultOption',1,'CancelOption',2);
                        switch selection
                            case 'Retry'
                                % try again
                            case 'Cancel'
                                retry=false;
                                app.APAddressEditField.BackgroundColor = [1 0 0];
                                return
                        end
                    else
                        retry=false;
                        app.APAddressEditField.BackgroundColor = [1 1 1];
%                         app.updateDisplays();
                    end
                end
            else
                app.APAddressEditField.BackgroundColor = [1 1 0];
                app.skipUpdate = true;
                app.AutoUpdateCheckBox.Enable = 'off';
            end
            % allow changes again
            app.APAddressEditField.Enable=true;
            app.PMAddressEditField.Enable=true;
            app.ChannelEditField.Enable=true;
        end

        % Value changed function: PMAddressEditField
        function PMAddressEditFieldValueChanged(app, event)
            if ~app.ModifyPMCheckBox.Value 
                % prevent changes before completing current change
                app.APAddressEditField.Enable=false;
                app.PMAddressEditField.Enable=false;
                app.ChannelEditField.Enable=false;
                retry=true;
                while retry
                    if~app.nnp.setRadio('addrPM', app.PMAddressEditField.Value)
                        fig=app.RadioConfigurationUIFigure;
                        msg='Error writing PM address.  Retry?';
                        title= 'Retry Settings Update';
                        selection = uiconfirm(fig, msg,title,...
                            'Options',{'Retry','Cancel'},...
                            'DefaultOption',1,'CancelOption',2);
                        switch selection
                            case 'Retry'
                                % try again
                            case 'Cancel'
                                retry=false;
                                app.PMAddressEditField.BackgroundColor = [1 0 0];
                                return
                        end
                    else
                        retry=false;
                        app.PMAddressEditField.BackgroundColor = [1 1 1];
%                         app.updateDisplays();
                    end
                end
            else
                app.PMAddressEditField.BackgroundColor = [1 1 0];
                app.skipUpdate = true;
                app.AutoUpdateCheckBox.Enable = 'off';
            end
             % allow changes again
            app.APAddressEditField.Enable=true;
            app.PMAddressEditField.Enable=true;
            app.ChannelEditField.Enable=true;
        end

        % Value changed function: ChannelEditField
        function ChannelEditFieldValueChanged(app, event)
            if ~app.ModifyPMCheckBox.Value 
                % prevent changes before completing current change
                app.APAddressEditField.Enable=false;
                app.PMAddressEditField.Enable=false;
                app.ChannelEditField.Enable=false;
                retry=true;
                while retry
                    if ~app.nnp.setRadio('chan', app.ChannelEditField.Value)
                        fig=app.RadioConfigurationUIFigure;
                        msg='Could not set channel on Access Point.  Retry?';
                        title= 'Retry Settings Update';
                        selection = uiconfirm(fig, msg,title,...
                            'Options',{'Retry','Cancel'},...
                            'DefaultOption',1,'CancelOption',2);
                        switch selection
                            case 'Retry'
                                % try again
                            case 'Cancel'
                                retry=false;
                                app.ChannelEditField.BackgroundColor = [1 0 0];
                                return
                        end
                    else
                        retry=false;
                        app.ChannelEditField.BackgroundColor = [1 1 1];
%                         app.updateDisplays();
                    end
                end
            else
                app.ChannelEditField.BackgroundColor = [1 1 0];
                app.skipUpdate = true;
                app.AutoUpdateCheckBox.Enable = 'off';
            end
             % allow changes again
            app.APAddressEditField.Enable=true;
            app.PMAddressEditField.Enable=true;
            app.ChannelEditField.Enable=true;
        end

        % Value changed function: WOREditField
        function WOREditFieldValueChanged(app, event)
            if ~app.ModifyPMCheckBox.Value 
                if ~app.nnp.setRadio('worInt', app.WOREditField.Value)
                    msgbox('Could not set wake interval on AccessPoint', 'AP Configuration', 'error');
                    app.WOREditField.BackgroundColor = [1 0 0];
                else
                    app.WOREditField.BackgroundColor = [1 1 1];
                end
                app.updateDisplays();
            else
                app.WOREditField.BackgroundColor = [1 1 0];
                app.skipUpdate = true;
                app.AutoUpdateCheckBox.Enable = 'off';
            end
        end

        % Value changed function: PMTXPowerEditField
        function PMTXPowerEditFieldValueChanged(app, event)
            value = app.PMTXPowerEditField.Value;
            msgbox('Not yet implemented', 'PM Configuration', 'help');
        end

        % Value changed function: APTXPowerEditField
        function APTXPowerEditFieldValueChanged(app, event)
             if ~app.nnp.setRadio('txPower', app.APTXPowerEditField.Value)
                  msgbox('Could not set Transmit Power on AccessPoint', 'AP Configuration', 'error');
             end
             app.updateDisplays();
        end

        % Value changed function: APRadioTimeoutEditField
        function APRadioTimeoutEditFieldValueChanged(app, event)
             if ~app.nnp.setRadio('rxTimeout', app.APRadioTimeoutEditField.Value)
                  msgbox('Could not set Radio Timeout on AccessPoint', 'AP Configuration', 'error');
             end
             app.updateDisplays();
        end

        % Value changed function: APUSBTimeoutEditField
        function APUSBTimeoutEditFieldValueChanged(app, event)
            value = app.APUSBTimeoutEditField.Value;
            switch app.APRadioTimeoutEditField.Value
                case 251, timeout = 1;
                case 252, timeout = 4;
                case 253, timeout = 10;
                case 254, timeout = 40;
                otherwise, timeout = app.APRadioTimeoutEditField.Value/1000;
            end
            
            recTimeout = (app.WOREditField.Value/1000 + timeout)*app.APRadioRetriesEditField.Value;
            
            if value < recTimeout
                msgbox({['Recommended USB timeout is at least ', num2str(recTimeout), 's.']; ...
                          '(Wake Interval[in s] + Radio Timeout[in s])*Radio Retries)'}, 'AP Configuration', 'help') 
            end
            app.nnp.timeout = value;
            app.updateDisplays();
        end

        % Button pushed function: SaveAPsettingsButton
        function SaveAPsettingsButtonPushed(app, event)
            app.skipUpdate = true;
            settingsIn = app.nnp.getRadioSettings(); % get the current, temporary settings
            if isempty(settingsIn)
                msgbox('Could not read radio settings', 'AP Configuration', 'error');
                app.skipUpdate = false;
                app.updateDisplays();
                return;
            end
            settings = app.nnp.setRadioSettings(settingsIn, true);  % save the temporary settings to saved
            if ~isequal(settings, settingsIn)   % if they did not save correctly...
                msgbox('Settings were not saved as expected', 'AP Configuration', 'error');
                app.APAddressEditField.Value = settings.addrAP;
                app.PMAddressEditField.Value = settings.addrPM;
                app.ChannelEditField.Value   = settings.chan;
                app.APTXPowerEditField.Value = settings.txPower;
                app.WOREditField.Value       = settings.worInt;
                app.APRadioTimeoutEditField.Value = settings.rxTimeout;
                app.APRadioRetriesEditField.Value = settings.retries;
                app.APUSBTimeoutEditField.Value   = app.nnp.timeout;
                
                if settings.worInt > 0
                    app.WakeOnRadioCheckbox.Value = 1;
                else
                    app.WakeOnRadioCheckbox.Value = 0;
                end
            else
                msgbox('Settings successfully saved.', 'AP Configuration', 'help');
            end
            app.skipUpdate = false;
            app.updateDisplays();
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            radioConfig =   [app.PMAddressEditField.Value,...
                             app.APAddressEditField.Value,...
                             app.ChannelEditField.Value];
            resp = app.nnp.write(7, '2600', 1, uint8(radioConfig), 3, 'uint8');
            if resp ~= 0
                msgbox('Could not write settings to PM.  Make sure AP settings are correct to communicate with PM',...
                            'AP + PM Configuration', 'error');
            else
                resp = app.nnp.read(7, '2600', 1, 3, 'uint8');
                if length(resp)==3 && all(resp == radioConfig)
                    resp = app.nnp.nmt(7, '92');  %NMT_RadioAddress
                    if resp ~= hex2dec('92')
                        msgbox('Could not apply radio settings in PM', 'AP + PM Configuration', 'error');
                    else
                        %succeded in changing PM.  Now change AP to match
                        app.nnp.setRadio('addrPM', app.PMAddressEditField.Value,...
                                         'addrAP', app.APAddressEditField.Value,...
                                         'chan',   app.ChannelEditField.Value);
                    end
                else
                     msgbox('Settings read back from PM do not match written settings.',...
                                'AP + PM Configuration', 'error');
                end
            end
            app.AutoUpdateCheckBox.Enable = 'on';
            app.skipUpdate = false;
            app.updateDisplays();
            
        end

        % Value changed function: WakeOnRadioCheckBox
        function WakeOnRadioCheckBoxValueChanged(app, event)
            if ~app.ModifyPMCheckBox.Value 
                if app.WakeOnRadioCheckBox.Value 
                    app.WOREditField.Enable = 'on';
                    if ~app.nnp.setRadio('worInt', app.WOREditField.Value)
                          msgbox('Could not set Wake Interval on AccessPoint', 'AP Configuration', 'error');
                    end
                else
                    app.WOREditField.Enable = 'off';
                    if ~app.nnp.setRadio('worInt', 0)
                          msgbox('Could not set Wake Interval on AccessPoint', 'AP Configuration', 'error');
                    end
                end 
            else
                app.skipUpdate = true;
                app.AutoUpdateCheckBox.Enable = 'off';
                if app.WakeOnRadioCheckBox.Value 
                    app.WOREditField.Enable = 'on';
                    if ~app.nnp.worOn(app.WOREditField.Value)
                        msgbox('Could not turn on WOR on PM', 'AP+PM Configuration', 'error');
                    end
                else
                    app.WOREditField.Enable = 'off';
                    if ~app.nnp.worOff()
                        msgbox('Could not turn off WOR on PM', 'AP+PM Configuration', 'error');
                    end
                end
            end
            
            app.skipUpdate = false;
            app.AutoUpdateCheckBox.Enable = 'on';
            app.updateDisplays();
        end

        % Close request function: RadioConfigurationUIFigure
        function RadioConfigurationUIFigureCloseRequest(app, event)
           app.AutoUpdateCheckBox.Value = 0;
%             pause(.1);
            if ~isempty(app.updateTimer)
                if isvalid(app.updateTimer)
                    stop(app.updateTimer);
%                      pause(.5);
                end
                 delete(app.updateTimer);
                
            end
            
%             settings = app.nnp.getRadioSettings();
%             if settings.addrAP    ~= app.savedSettings.addrAP || ...
%                settings.addrPM    ~= app.savedSettings.addrPM || ...
%                settings.chan      ~= app.savedSettings.chan ||...
%                settings.txPower   ~= app.savedSettings.txPower||...
%                settings.worInt    ~= app.savedSettings.worInt||...
%                settings.rxTimeout ~= app.savedSettings.rxTimeout||...
%                settings.retries   ~= app.savedSettings.retries
%            
%                resp = questdlg('Some AccessPoint radio settings have been changed. Do you want to save the new settings to AP Flash, so they will be preserved after power cycling the AP?',...
%                    'AP Configuration');
%                if isequal(resp, 'Yes')
%                    app.SaveAPsettingsButtonPushed(event); %trigger save callback
%                end
%             end
            if ishandle(app.callingFigure) % check that is is valid
                figure(app.callingFigure); % bring to front
            end
%               pause(.5);
            delete(app)
            
        end

        % Value changed function: APRadioRetriesEditField
        function APRadioRetriesEditFieldValueChanged(app, event)
            if ~app.nnp.setRadio('retries', app.APRadioRetriesEditField.Value)
                  msgbox('Could not set Radio Retries on AccessPoint', 'AP Configuration', 'error');
             end
             app.updateDisplays();
            
        end

        % Value changed function: ModifyPMCheckBox
        function ModifyPMCheckBoxValueChanged(app, event)
            if app.ModifyPMCheckBox.Value 
                app.ApplyButton.Enable = 'on';
            else
                app.ApplyButton.Enable = 'off';
                app.AutoUpdateCheckBox.Enable = 'on';
                app.skipUpdate = false;  
            end
        end

        % Menu selected function: HelpMenu
        function help(app, event)
            msgbox({'If you do not know PM local/remote address or channel, use the PM bootloader to read (and/or set) them';...
                    '';...
                    '----Interpretting RSSI/LQI----';...
                    '* Low RSSI and high LQI:';...
                    '    A weak signal in the presence of noise';...
                    '* Low RSSI and low LQI:';...
                    '    A weak signal in absence of noise';...
                    '* High RSSI and high LQI:';... 
                    '    Strong noise (usually coming from an interferer)';...
                    '* Very high RSSI and high LQI:';... 
                    '    A very strong signal that causes the receiver';...
                    '    to saturate.  ove AP farther from PM';... 
                    '* High RSSI and low LQI:';... 
                    '   A strong signal without much noise ';... 
                    '';...
                    'Common Settings are only applied to the PM if "Modify PM" is checked and the AP is currently configured to communicate with the PM. In this case, boxes wit unapplied changes are highlighted in yellow, and AutoUpdates will be disabled. The changes will be applied to both the AP and PM upon hitting the "Apply" button. Which will also reenable the AutoUpdates.  If "Modify PM" is not checked, changes are applied on the AP immmediately.' },...
                    'Radio Configuration Help');
        end

        % Value changed function: AutoUpdateCheckBox
        function AutoUpdateCheckBoxValueChanged(app, event)
            app.retryCount = 0;         
            if app.AutoUpdateCheckBox.Value
                app.RadioConfigurationUIFigure.WindowStyle='alwaysontop';
            else
                app.RadioConfigurationUIFigure.WindowStyle='normal';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RadioConfigurationUIFigure = uifigure('Visible', 'off');
            app.RadioConfigurationUIFigure.Name = 'Radio Configuration';
            app.RadioConfigurationUIFigure.CloseRequestFcn = createCallbackFcn(app, @RadioConfigurationUIFigureCloseRequest, true);
            app.HelpMenu = uimenu(app.RadioConfigurationUIFigure);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @help, true);
            app.HelpMenu.Text = 'Help';
            app.RSSIAxes = uiaxes(app.RadioConfigurationUIFigure);
            ylabel(app.RSSIAxes, 'RSSI (dBm)')
            app.LQIAxes = uiaxes(app.RadioConfigurationUIFigure);
            xlabel(app.LQIAxes, 'sample')
            ylabel(app.LQIAxes, 'LQI')
            app.CommonSettingsPanel = uipanel(app.RadioConfigurationUIFigure);
            app.CommonSettingsPanel.Title = 'Common Settings';
            app.APAddressEditFieldLabel = uilabel(app.CommonSettingsPanel);
            app.APAddressEditFieldLabel.Text = 'AP Address';
            app.APAddressEditField = uieditfield(app.CommonSettingsPanel, 'numeric');
            app.APAddressEditField.Limits = [1 254];
            app.APAddressEditField.ValueChangedFcn = createCallbackFcn(app, @APAddressEditFieldValueChanged, true);
            app.APAddressEditField.Value = 3;
            app.PMAddressEditFieldLabel = uilabel(app.CommonSettingsPanel);
            app.PMAddressEditFieldLabel.Text = 'PM Address';
            app.PMAddressEditField = uieditfield(app.CommonSettingsPanel, 'numeric');
            app.PMAddressEditField.Limits = [1 254];
            app.PMAddressEditField.ValueChangedFcn = createCallbackFcn(app, @PMAddressEditFieldValueChanged, true);
            app.PMAddressEditField.Value = 4;
            app.ChannelEditFieldLabel = uilabel(app.CommonSettingsPanel);
            app.ChannelEditFieldLabel.Text = 'Channel';
            app.ChannelEditField = uieditfield(app.CommonSettingsPanel, 'numeric');
            app.ChannelEditField.Limits = [0 9];
            app.ChannelEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelEditFieldValueChanged, true);
            app.ChannelEditField.Value = 5;
            app.WakeintervalmsEditFieldLabel = uilabel(app.CommonSettingsPanel);
            app.WakeintervalmsEditFieldLabel.Text = 'Wake interval (ms)';
            app.WOREditField = uieditfield(app.CommonSettingsPanel, 'numeric');
            app.WOREditField.Limits = [14 254];
            app.WOREditField.ValueChangedFcn = createCallbackFcn(app, @WOREditFieldValueChanged, true);
            app.WOREditField.Value = 20;
            app.ApplyButton = uibutton(app.CommonSettingsPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Enable = 'off';
            app.ApplyButton.Text = 'Apply';
            app.WakeOnRadioCheckBox = uicheckbox(app.CommonSettingsPanel);
            app.WakeOnRadioCheckBox.ValueChangedFcn = createCallbackFcn(app, @WakeOnRadioCheckBoxValueChanged, true);
            app.WakeOnRadioCheckBox.Text = 'Wake On Radio';
            app.WakeOnRadioCheckBox.Value = true;
            app.ModifyPMCheckBox = uicheckbox(app.CommonSettingsPanel);
            app.ModifyPMCheckBox.ValueChangedFcn = createCallbackFcn(app, @ModifyPMCheckBoxValueChanged, true);
            app.ModifyPMCheckBox.Text = 'Modify PM';
            app.IfcheckedchangeswillbeLabel = uilabel(app.CommonSettingsPanel);
            app.IfcheckedchangeswillbeLabel.Text = {'If checked, changes will be'; 'applied on both AP and PM '; 'after hitting Apply. '; 'If unchecked, changes will  '; 'be applied to AP '; 'immediately'};
            app.PowerModuleSettingsPanel = uipanel(app.RadioConfigurationUIFigure);
            app.PowerModuleSettingsPanel.Title = 'Power Module Settings';
            app.PMTXPowerEditFieldLabel = uilabel(app.PowerModuleSettingsPanel);
            app.PMTXPowerEditFieldLabel.Text = 'PM TX Power';
            app.PMTXPowerEditField = uieditfield(app.PowerModuleSettingsPanel, 'numeric');
            app.PMTXPowerEditField.Limits = [0 46];
            app.PMTXPowerEditField.ValueChangedFcn = createCallbackFcn(app, @PMTXPowerEditFieldValueChanged, true);
            app.PMTXPowerEditField.Value = 20;
            app.AccessPointSettingsPanel = uipanel(app.RadioConfigurationUIFigure);
            app.AccessPointSettingsPanel.Title = 'Access Point Settings';
            app.hint = uilabel(app.AccessPointSettingsPanel);
            app.hint.Text = {'0-250 (ms)'; '251= 1s '; '252= 4s'; '253=10s'; '254=40s'};
            app.APRadiotimeoutEditFieldLabel = uilabel(app.AccessPointSettingsPanel);
            app.APRadiotimeoutEditFieldLabel.Text = 'AP Radio timeout';
            app.APRadioTimeoutEditField = uieditfield(app.AccessPointSettingsPanel, 'numeric');
            app.APRadioTimeoutEditField.Limits = [0 254];
            app.APRadioTimeoutEditField.ValueChangedFcn = createCallbackFcn(app, @APRadioTimeoutEditFieldValueChanged, true);
            app.APRadioTimeoutEditField.Value = 100;
            app.APUSBtimeoutsEditFieldLabel = uilabel(app.AccessPointSettingsPanel);
            app.APUSBtimeoutsEditFieldLabel.Text = 'AP USB timeout (s)';
            app.APUSBTimeoutEditField = uieditfield(app.AccessPointSettingsPanel, 'numeric');
            app.APUSBTimeoutEditField.Limits = [0 60];
            app.APUSBTimeoutEditField.ValueChangedFcn = createCallbackFcn(app, @APUSBTimeoutEditFieldValueChanged, true);
            app.APUSBTimeoutEditField.Value = 0.5;
            app.APTXPowerEditFieldLabel = uilabel(app.AccessPointSettingsPanel);
            app.APTXPowerEditFieldLabel.Text = 'AP TX Power';
            app.APTXPowerEditField = uieditfield(app.AccessPointSettingsPanel, 'numeric');
            app.APTXPowerEditField.Limits = [0 46];
            app.APTXPowerEditField.ValueChangedFcn = createCallbackFcn(app, @APTXPowerEditFieldValueChanged, true);
            app.APTXPowerEditField.Value = 20;
            app.APRadioretriesEditFieldLabel = uilabel(app.AccessPointSettingsPanel);
            app.APRadioretriesEditFieldLabel.Text = 'AP Radio retries';
            app.APRadioRetriesEditField = uieditfield(app.AccessPointSettingsPanel, 'numeric');
            app.APRadioRetriesEditField.Limits = [0 5];
            app.APRadioRetriesEditField.ValueChangedFcn = createCallbackFcn(app, @APRadioRetriesEditFieldValueChanged, true);
            app.APRadioRetriesEditField.Value = 2;
            app.APEditFieldLabel = uilabel(app.RadioConfigurationUIFigure);
            app.APEditFieldLabel.Text = 'AP ';
            app.APRSSIEditField = uieditfield(app.RadioConfigurationUIFigure, 'numeric');
            app.APRSSIEditField.Value = Inf;
            app.PMEditFieldLabel = uilabel(app.RadioConfigurationUIFigure);
            app.PMEditFieldLabel.Text = 'PM';
            app.PMRSSIEditField = uieditfield(app.RadioConfigurationUIFigure, 'numeric');
            app.PMRSSIEditField.Value = Inf;
            app.APEditField_2Label = uilabel(app.RadioConfigurationUIFigure);
            app.APEditField_2Label.Text = 'AP ';
            app.APLQIEditField = uieditfield(app.RadioConfigurationUIFigure, 'numeric');
            app.APLQIEditField.Value = Inf;
            app.PMEditField_2Label = uilabel(app.RadioConfigurationUIFigure);
            app.PMEditField_2Label.Text = 'PM';
            app.PMLQIEditField = uieditfield(app.RadioConfigurationUIFigure, 'numeric');
            app.PMLQIEditField.Value = Inf;
            app.SaveAPsettingsButton = uibutton(app.RadioConfigurationUIFigure, 'push');
            app.SaveAPsettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAPsettingsButtonPushed, true);
            app.SaveAPsettingsButton.Text = 'Save AP settings';
            app.AutoUpdateCheckBox = uicheckbox(app.RadioConfigurationUIFigure);
            app.AutoUpdateCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoUpdateCheckBoxValueChanged, true);
            app.AutoUpdateCheckBox.Text = 'Auto Update';
            app.AutoUpdateCheckBox.Value = true;
            app.Lamp = uilamp(app.RadioConfigurationUIFigure);
            app.PMSerialEditFieldLabel = uilabel(app.RadioConfigurationUIFigure);
            app.PMSerialEditFieldLabel.Text = 'PM Serial#';
            app.PMSerialEditField = uieditfield(app.RadioConfigurationUIFigure, 'numeric');
            app.PMSerialEditField.Value = Inf;
            % Show the figure after all components are created
            app.RadioConfigurationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = radioconfig(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RadioConfigurationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RadioConfigurationUIFigure)
        end
    end
end