classdef readWrite1F57 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PM1F57                  matlab.ui.Figure
        FileMenu                matlab.ui.container.Menu
        OpenMenu                matlab.ui.container.Menu
        SaveMenu                matlab.ui.container.Menu
        SaveAsMenu              matlab.ui.container.Menu
        FilenameEditField       matlab.ui.control.EditField
        FilenameEditFieldLabel  matlab.ui.control.Label
        Panel                   matlab.ui.container.Panel
        PMVersionButton         matlab.ui.control.Button
        MessagesLabel           matlab.ui.control.Label
        ReadPM                  matlab.ui.control.Button
        WritePM                 matlab.ui.control.Button
        SavePGButton            matlab.ui.control.Button
        ClearMessageButton      matlab.ui.control.Button
        Messages                matlab.ui.control.TextArea
        N71F57Label             matlab.ui.control.Label
        UITable                 matlab.ui.control.Table
    end

    
    properties (Access = private)
       callingFigure=[]; %handle to calling figure
    end
    
    properties (Access = public)
        nnp=[]; % nnpapi
         A57=zeros(150,1); % PM 1F57 array
         aSize=150; % size of array
        filename57=[];
        pathname=[];
    end
    
    methods (Access = public)
        
        function ClearMessages(app)
           app.Messages.Value=''; % clear messages 
        end
        
        function TopMessage(app,message)
            app.Messages.Value=vertcat(message, string(app.Messages.Value)); % put new message on top
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin<2
                NNP=[];
            else
                NNP=varargin{1};
            end
            if isempty(NNP)
                app.nnp = NNPAPI();
                if isempty(app.nnp.port)
                    msgbox('No port chosen for NNP Access Point')
                    delete(app.nnp)
                    return
                end
            else
                app.nnp = NNP;
            end
            if nargin>2
                app.callingFigure=varargin{2};
            end

            
            % read in variables
            ld=load('Table1F57.mat');
            app.UITable.Data = ld.table1F57;
            s = uistyle('BackgroundColor',[.9,1,1],'FontWeight','bold');
            addStyle(app.UITable,s,'column',3)
            app.A57=app.UITable.Data{:,3}; % column 3
        end

        % Button pushed function: ReadPM
        function ReadPMPushed(app, event)
            % determine size of array in PM
            resp=app.nnp.read(7,'1F57',0,1,'uint8'); % size of array
            if(length(resp)==1) %valid packet
                % if the table size is less than 150, need to change the
                % whole table.
                app.aSize=resp;
                if app.aSize==100
                    app.UITable.Data=app.UITable.Data(1:100,:);
                end
                new57=app.A57(1:app.aSize); % initialize with starting data correct size
                % Read in two or three reads
                resp=app.nnp.read(7,'1F57',1,50,'uint8');
                if(length(resp)==50) %valid packet
                    new57(1:50)=double(resp);
                    resp=app.nnp.read(7,'1F57',51,50,'uint8');
                    if(length(resp)==50) %valid packet
                        new57(51:100)=double(resp);
                        if app.aSize==150
                            resp=app.nnp.read(7,'1F57',101,50,'uint8');
                            if(length(resp)==50) %valid packet
                                new57(101:150)=double(resp);
                                app.A57=new57;
                                app.UITable.Data{:,3}=app.A57;
                                dmessage='Read PM 1F57';
                                app.TopMessage(dmessage);
                            else
                                dmessage='error reading PM';
                                app.TopMessage(dmessage);
                                return;
                            end
                        else
                            app.A57=new57;
                            app.UITable.Data{:,3}=app.A57;
                            dmessage='Read PM 1F57';
                            app.TopMessage(dmessage);
                        end
                    else
                        dmessage='error reading PM';
                        app.TopMessage(dmessage);
                        return;
                    end
                else
                    dmessage='error reading PM';
                    app.TopMessage(dmessage);
                    return;
                end

                app.UITable.Data{:,3}=app.A57;
            end
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            [filename, pathname ] = uigetfile('*.mat', 'Open PM 1F57 mat File');
            if filename == 0
                return
            else
                app.filename57 = [pathname filename];
                app.FilenameEditField.Value=app.filename57;
            end
            
            %             app.rmParam = load(app.rmfilename);
            in=load(app.filename57);
            if ~isfield(in, 'table1F57') % this file does not contain the right structure
                % here I could test also test the number of fields in rmParam
                msgbox('mat file must contain the table named "table1F57". Please try again.');
            else
                
                app.UITable.Data = in.table1F57;
                s = uistyle('BackgroundColor',[.9,1,1],'FontWeight','bold');
                addStyle(app.UITable,s,'column',3)
                app.A57=app.UITable.Data{:,3}; % column 3
                
                app.TopMessage('Data loaded from file');
            end
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
                    %check for valid file name.   get new name if not valid
            % save data to file
            if isempty(app.filename57)
                SaveAsMenuSelected(app, event);
            else
                %create a table from the data
                table1F57=app.UITable.Data; % creates table
                save(app.filename57,'table1F57'); % save the whole structure as a variable, rather than individual
                app.TopMessage('Data saved to file');    
            end
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
           % get a file name and save the data to that file
            % include a default file name.  How about rmParameters.mat
            [filename, pathname ] = uiputfile('*.mat', 'Save As 1F57 mat File','Pnnn_T1F57.mat');
            if filename == 0
                return
            else
                app.filename57 = fullfile(pathname, filename);
                SaveMenuSelected(app, event);
                app.pathname=pathname;
                app.FilenameEditField.Value=app.filename57;
            end
        end

        % Button pushed function: WritePM
        function WritePMButtonPushed(app, event)
           % copy data to app.A57
           app.A57=app.UITable.Data{:,3}; % Third column of data is the needed data in double format
            % Write in three writes
            result=app.nnp.write(7,'1F57',1,uint8(app.A57(1:50)'),'uint8',50);
            if ~isequal(result, 0)
                app.TopMessage('Error Writing 1-50 to PM');
                return;
            else
                app.TopMessage('1-50 to PM');
            end 
            result=app.nnp.write(7,'1F57',51,uint8(app.A57(51:100)'),'uint8',50);
            if ~isequal(result, 0)
                app.TopMessage('Error Writing 51-100 to PM');
                return;
            else
                app.TopMessage('51-100 to PM');
            end 
            result=app.nnp.write(7,'1F57',101,uint8(app.A57(101:150)'),'uint8',50);
            if ~isequal(result, 0)
                app.TopMessage('Error Writing 101-150 to PM');
                return;
            else
                app.TopMessage('101-150 to PM');
            end             
        end

        % Button pushed function: SavePGButton
        function SavePGButtonPushed(app, event)
            if app.nnp.saveOD(7)
                    dmessage = 'PM OD Saved';
            else
                dmessage = 'Failed PM OD Save';
            end
            app.TopMessage(dmessage);
        end

        % Button pushed function: PMVersionButton
        function PMVersionButtonPushed(app, event)
            resp=double(app.nnp.read(7,'1018',3,'uint32')); % read revision number to test communications
            if ~isempty(resp)
                dmessage=sprintf('PM version %d',resp);
            else
                dmessage='PM not read';
            end
            app.TopMessage(dmessage);
            
        end

        % Button pushed function: ClearMessageButton
        function ClearMessageButtonPushed(app, event)
            app.Messages.Value=' ';
        end

        % Close request function: PM1F57
        function PM1F57CloseRequest(app, event)
            if ishandle(app.callingFigure) % check that is is valid
                figure(app.callingFigure); % bring to front
            end
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PM1F57 = uifigure('Visible', 'off');
            app.PM1F57.Name = 'Power Module OD 1F57';
            app.PM1F57.CloseRequestFcn = createCallbackFcn(app, @PM1F57CloseRequest, true);
            app.FileMenu = uimenu(app.PM1F57);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Text = 'SaveAs';
            app.UITable = uitable(app.PM1F57);
            app.UITable.ColumnName = {'Dec'; 'Hex'; 'Value'; 'Description'; 'Note'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false false true false false];
            app.N71F57Label = uilabel(app.PM1F57);
            app.N71F57Label.Text = 'N7:1F57';
            app.Panel = uipanel(app.PM1F57);
            app.Panel.Title = 'Panel';
            app.Messages = uitextarea(app.Panel);
            app.ClearMessageButton = uibutton(app.Panel, 'push');
            app.ClearMessageButton.ButtonPushedFcn = createCallbackFcn(app, @ClearMessageButtonPushed, true);
            app.ClearMessageButton.Text = 'clear';
            app.SavePGButton = uibutton(app.Panel, 'push');
            app.SavePGButton.ButtonPushedFcn = createCallbackFcn(app, @SavePGButtonPushed, true);
            app.SavePGButton.Text = {'Save'; 'OD'};
            app.WritePM = uibutton(app.Panel, 'push');
            app.WritePM.ButtonPushedFcn = createCallbackFcn(app, @WritePMButtonPushed, true);
            app.WritePM.Text = {'Write'; 'PM'};
            app.ReadPM = uibutton(app.Panel, 'push');
            app.ReadPM.ButtonPushedFcn = createCallbackFcn(app, @ReadPMPushed, true);
            app.ReadPM.Text = {'Read'; 'PM'};
            app.MessagesLabel = uilabel(app.Panel);
            app.MessagesLabel.Text = 'Messages';
            app.PMVersionButton = uibutton(app.Panel, 'push');
            app.PMVersionButton.ButtonPushedFcn = createCallbackFcn(app, @PMVersionButtonPushed, true);
            app.PMVersionButton.Text = {'PM'; 'Version'};
            app.FilenameEditFieldLabel = uilabel(app.PM1F57);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.PM1F57, 'text');
            % Show the figure after all components are created
            app.PM1F57.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = readWrite1F57(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PM1F57)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PM1F57)
        end
    end
end