classdef rmParametersStatus < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        UserIDEditField    matlab.ui.control.EditField
        NetworkLamp        matlab.ui.control.Lamp
        Messages           matlab.ui.control.EditField
        StopNetworkButton  matlab.ui.control.Button
        ScanningLabel      matlab.ui.control.Label
        RemoteModInfo      matlab.ui.control.TextArea
        SavetoFileButton   matlab.ui.control.Button
        ScanButton         matlab.ui.control.Button
        DisplayRemoteModuleInformationandStatusLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        nnp = [];
        defnodes=[1:6,9]; % default nodes to scan
        RMinfoString=[]; % string to write to display
        callingFigure=[]; %handle to calling figure
        % Code for auto userID open
         userFolders=struct;  % userfiles, code, user, config, data, log, where code is file prefix
         defaultSuffix='_RMStatus'; %before all the date/time information is added
         defaultName='P00_RMStatus'; %if userFolders is not valid use this
         defaultPath=pwd;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            app.ScanningLabel.Visible='off';
            if nargin<2
                NNP=[];
            else
                NNP=varargin{1};
            end
            useNNP=true;
            if isempty(NNP) || ~isvalid(NNP) %if being called after nnp cancelled from another app
                app.nnp = NNPAPI();
                if isempty(app.nnp.port)
                    %msgbox('No port chosen for NNP Access Point')
                    uialert(app.UIFigure,'No port chosen for NNP Access Point','No NNP AP')
                    delete(app.nnp)
                    useNNP=false;
%                     return
                end
            else
                app.nnp = NNP;
            end
            if nargin>2
                app.callingFigure=varargin{2};
            end
            if nargin>3  % third is userFolders
                app.userFolders=varargin{3};  % userfiles, code, user, config, data, log
                if isfield(app.userFolders,'code')&& ~isempty(app.userFolders.code)
                    app.UserIDEditField.Value=app.userFolders.code;
                end
                if isfield(app.userFolders,'log')&& ~isempty(app.userFolders.log)
                    app.defaultName=[app.userFolders.code,app.defaultSuffix]; % name without extension
                    app.defaultPath=app.userFolders.log;
                end   
            end
            pause(.1);
            % get network status
            if useNNP
                resp=app.nnp.getNetworkStatus();
            else
                resp=[];
            end
            if length(resp)==1
                if resp==1
                    app.NetworkLamp.Color=[0,1,0];
                end
            else
                app.Messages.Value='Could not read network status';
            end
        end

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            app.Messages.Value='';
            app.ScanningLabel.Visible='on';
            if app.nnp.enterWaiting()
                if app.nnp.networkOn()
                    app.NetworkLamp.Color=[0,1,0];
                    pause(.5); %wait for network to start
                    app.RMinfoString=rmSettingsReadFcn(app.nnp,app.defnodes);
                    app.RemoteModInfo.Value=app.RMinfoString;
                else
                    app.Messages.Value='Enter Waiting failed';
                end
            else
                app.Messages.Value='Network On failed';
            end
            app.ScanningLabel.Visible='off';

           
        end

        % Button pushed function: StopNetworkButton
        function StopNetworkButtonPushed(app, event)
            app.Messages.Value='';
            if ~app.nnp.enterWaiting()
                app.Messages.Value='Enter Waiting failed';
                return;
            end
            if ~app.nnp.networkOff()
                app.Messages.Value='Network Off failed';
                return;
            else
                app.NetworkLamp.Color=[0.9,0.9,0.9];
            end
        end

        % Button pushed function: SavetoFileButton
        function SavetoFileButtonPushed(app, event)
            % Get file name and write string to file.
            timestring=sprintf('_%02.0f',datevec(now));
            defaultname=[app.defaultName,timestring,'.txt'];
            defName=fullfile(app.defaultPath,defaultname);
            [fn,pn]=uiputfile('*.txt','Specify Filename and location for output text file',defName);
            if fn  %will be 0 if cancelled
                savename=fullfile(pn,fn);
                SaveID=fopen(savename,'a'); %open in append mode in case I select the wrong file
                fprintf(SaveID,'%s',app.RMinfoString);
                fclose(SaveID);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ishandle(app.callingFigure) % check that is is valid
                figure(app.callingFigure); % bring to front
            end
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Remote Module Information';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DisplayRemoteModuleInformationandStatusLabel = uilabel(app.UIFigure);
            app.DisplayRemoteModuleInformationandStatusLabel.Text = 'Display Remote Module Information and Status';
            app.ScanButton = uibutton(app.UIFigure, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.SavetoFileButton = uibutton(app.UIFigure, 'push');
            app.SavetoFileButton.ButtonPushedFcn = createCallbackFcn(app, @SavetoFileButtonPushed, true);
            app.SavetoFileButton.Text = {'Save '; 'to File'};
            app.RemoteModInfo = uitextarea(app.UIFigure);
            app.ScanningLabel = uilabel(app.UIFigure);
            app.ScanningLabel.Text = 'Scanning ...';
            app.StopNetworkButton = uibutton(app.UIFigure, 'push');
            app.StopNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @StopNetworkButtonPushed, true);
            app.StopNetworkButton.Text = {'Stop'; 'Network'};
            app.Messages = uieditfield(app.UIFigure, 'text');
            app.NetworkLamp = uilamp(app.UIFigure);
            app.UserIDEditField = uieditfield(app.UIFigure, 'text');
            app.UserIDEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rmParametersStatus(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end