classdef setChargeParameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ScanPanel                     matlab.ui.container.Panel
        ScanIntervalEditField         matlab.ui.control.NumericEditField
        scanIntLabel                  matlab.ui.control.Label
        ScanLED                       matlab.ui.control.Lamp
        ScanButton                    matlab.ui.control.StateButton
        Label                         matlab.ui.control.Label
        ReadValuesPanel               matlab.ui.container.Panel
        ReqCurrentEditField           matlab.ui.control.NumericEditField
        ReqCurrentEditFieldLabel      matlab.ui.control.Label
        ActualCurrentEditField        matlab.ui.control.NumericEditField
        ActualCurrentEditFieldLabel   matlab.ui.control.Label
        VrecEditField                 matlab.ui.control.NumericEditField
        VrecEditFieldLabel            matlab.ui.control.Label
        ClearMessageButton            matlab.ui.control.Button
        MessagesTextArea              matlab.ui.control.TextArea
        MessagesTextAreaLabel         matlab.ui.control.Label
        GainEditField                 matlab.ui.control.NumericEditField
        GainEditFieldLabel            matlab.ui.control.Label
        OffsetEditField               matlab.ui.control.NumericEditField
        OffsetEditFieldLabel          matlab.ui.control.Label
        VrecGauge                     matlab.ui.control.LinearGauge
        VrecGaugeLabel                matlab.ui.control.Label
        PMODPanel                     matlab.ui.container.Panel
        SaveODButton                  matlab.ui.control.Button
        WriteODButton                 matlab.ui.control.Button
        ReadODButton                  matlab.ui.control.Button
        OffsetSlider                  matlab.ui.control.Slider
        OffsetSliderLabel             matlab.ui.control.Label
        ChargeParametersPanel         matlab.ui.container.Panel
        NoChargeStepLimitEditField    matlab.ui.control.NumericEditField
        NoChargeStepLimitEditFieldLabel  matlab.ui.control.Label
        NoChargeLimitEditField        matlab.ui.control.NumericEditField
        NoChargeLimitEditFieldLabel   matlab.ui.control.Label
        StepIncrementEditField        matlab.ui.control.NumericEditField
        StepIncrementEditFieldLabel   matlab.ui.control.Label
        ChargeIntervalEditField       matlab.ui.control.NumericEditField
        ChargeIntervalEditFieldLabel  matlab.ui.control.Label
        CurrentPanel                  matlab.ui.container.Panel
        CDEditField                   matlab.ui.control.NumericEditField
        CDLabel                       matlab.ui.control.Label
        IntendedCurrentEditField      matlab.ui.control.NumericEditField
        IntendedCurrentLabel          matlab.ui.control.Label
        ManualOverrideButton          matlab.ui.control.StateButton
        GainSlider                    matlab.ui.control.Slider
        GainSliderLabel               matlab.ui.control.Label
        CurrentListBox                matlab.ui.control.ListBox
        CurrentListBoxLabel           matlab.ui.control.Label
        SetChargingParametersLabel    matlab.ui.control.Label
    end

    
    properties (Access = public)
        nnp=[]; % nnpapi
        noNNP=true; % add this so that I can run the app when disconnected
        currentArray=[55:10:95]; % amplitude in mA
        currentIndex=1; %starting index
        chargeCurrent=55;
        coilDriveTableData=uint16([55,65,75,85,95;559,439,499,469,439]');
        updateTimer = [];
        lastUpdateTime = 0;
    end
    
    properties (Access = private)
        callingFigure=[]; %handle to calling figure % Description
    end
    
    methods (Access = private)


        function updateDisplays(app, obj, event) % do I need obj, event if used with timer function?
            if ~app.noNNP
                % read array from 1f53
                app.ScanLED.Color = [0.80,0.80,0.80]; % to make LED blink slightly each cycle
                
                % I need to record the following:
                % Vrec 7, uint16, Com Current 12, uint8 
                % Read 3000.7-12 and ignore the intermediate bytes.  Would need
                % bytes 1-2 for Vrec and byte 10 for comm current
                resp=app.nnp.read(7,'3000',7,6,'uint8');
%               pause(.01);
                if(length(resp)==10) %valid packet
                      %make LED blink green by turning green while updating
                    app.ScanLED.Color = [0,1,0];    %normal [0.80,0.80,0.80], green,[0,1,0], red [ 1,0,0]
                    app.VrecEditField.Value=double(typecast(resp([1 2]),'uint16'))/10;
                    app.VrecGauge.Value=double(typecast(resp([1 2]),'uint16'))/10;
                    app.ReqCurrentEditField.Value=double(resp(9));
                    app.ActualCurrentEditField.Value=double(resp(10));
                   
%                     app.updateCoilDrive();
% Need to add update of OD inside of this loop or it won't occur during scan


                else
                    % make LED blink red
                    app.ScanLED.Color = [1,0,0];    %normal [0.80,0.80,0.80], green,[0,1,0], red [ 1,0,0]
                end
    %               pause(.1);
    %              app.ScanLED.Color = [0.80,0.80,0.80];    %normal [0.80,0.80,0.80], green,[0,1,0], red [ 1,0,0]
            end
        end
        
        function readChargeOD3000(app)
            if ~app.noNNP
                 resp=app.nnp.read(7,'3000',2,11,'uint8');%read indices 2-0xC as bytes
                         %assuming starting with index 2:
%              Subindex	start	bytes	type	variable
%              2	    1	    1	uint8	BatteryControl_BatteryChargingStepIncrement
%              3	    2	    2	uint16	BatteryControl_BatteryChargingInterval
%              4	    4	    2	uint16	BatteryControl_ChargeNFLinkNoChargeLimit
%              5	    6	    2	uint16	BatteryControl_ChargeNFLinkNoChargeStepLimit
%              6        8       1   uint8   BatteryChargeRun
%              7	    9	    2	uint16	BatteryControl_VREC*10
%              8        11	    2	uint16	VSYS
%              9        13	    2	uint16	VNET
%              10       15	    2	uint16	LOAD
%              11	    17	    1	uint8	BatteryControl Requested Charging Current
%              12	    18	    1	uint8	BatteryControl Commanded Charging Current
                if(length(resp)==18) %valid packet
                    % update values displayed
                    app.StepIncrementEditField.Value=double(resp(1));
                    app.ChargeIntervalEditField.Value=double(typecast(resp([2 3]),'uint16'));
                    app.NoChargeLimitEditField.Value=double(typecast(resp([4 5]),'uint16'));
                    app.NoChargeStepLimitEditField.Value=double(typecast(resp([6 7]),'uint16'));
                    app.VrecEditField.Value=double(typecast(resp([9 10]),'uint16'))/10;
                    app.VrecGauge.Value=double(typecast(resp([9 10]),'uint16'))/10; 
                    app.ReqCurrentEditField.Value=double(resp(17));
                    app.ActualCurrentEditField.Value=double(resp(18));
                    
                else
    %
                end
                resp=app.nnp.read(7,'1F57',145,3,'uint8');
                if(length(resp)==3) %valid packet
                    resp=double(resp); % since values require doubles
                    app.OffsetEditField.Value=resp(1);
                    app.OffsetSlider.Value=resp(1);
                    app.GainEditField.Value=resp(2);
                    app.GainSlider.Value=resp(2);
                    intendedCurrentIndex=resp(3); %need to create a variable for this
                    % if the index is between 1 and 5
                    % set manual to true and run manual change callback
                end
            end

        end
        
        function writeChargeOD3000(app)
            % write back the appropriate parameters to PM OD 3000
                         %assuming starting with index 2:
%              Subindex	start	bytes	type	variable
%              2	    1	    1	uint8	BatteryControl_BatteryChargingStepIncrement
%              3	    2	    2	uint16	BatteryControl_BatteryChargingInterval
%              4	    4	    2	uint16	BatteryControl_ChargeNFLinkNoChargeLimit
%              5	    6	    2	uint16	BatteryControl_ChargeNFLinkNoChargeStepLimit
%              6        8       1   uint8   BatteryChargeRun
%              7	    9	    2	uint16	BatteryControl_VREC*10
%              8        11	    2	uint16	VSYS
%              9        13	    2	uint16	VNET
%              10       15	    2	uint16	LOAD
%              11	    17	    1	uint8	BatteryControl Requested Charging Current
%              12	    18	    1	uint8	BatteryControl Commanded Charging Current
            % unlike the read, I am only going to write back four elements
% There may be a way to write this back as a block, but this was the safer way
            if ~app.noNNP
               if app.nnp.write(7,'3000',2,uint8(app.StepIncrementEditField.Value),'uint8')== 0
                    app.TopMessage('Step Increment');
               end
               if app.nnp.write(7,'3000',3,uint16(app.ChargeIntervalEditField.Value),'uint16')== 0
                   app.TopMessage('Charge Interval');
               end
               if app.nnp.write(7,'3000',4,uint16(app.NoChargeLimitEditField.Value),'uint16') == 0
                   app.TopMessage('No Charge Limit');
               end
              if  app.nnp.write(7,'3000',5,uint16(app.NoChargeStepLimitEditField.Value),'uint16') == 0
                   app.TopMessage('No Step Limit');
              end
            
%               app.updateCoilDrive();
              app.TopMessage('--');
            end
        end
        
        
        function startUpdate(app)

            % I should dim unwanted thing here
            app.ReadODButton.Enable=false;
            app.SaveODButton.Enable=false;
            app.WriteODButton.Enable=false;
%             app.WriteGOButton.Enable=false;
            app.TopMessage('starting timer');
            start(app.updateTimer);
        end

        function stopUpdate(app)
            app.TopMessage('stopping timer');
            stop(app.updateTimer);
            app.ScanLED.Color = [0.80,0.80,0.80];    %normal [0.80,0.80,0.80], green,[0,1,0], red [ 1,0,0]
            app.ReadODButton.Enable=true;
            app.SaveODButton.Enable=true;
            app.WriteODButton.Enable=true;
%             app.WriteGOButton.Enable=true;
        end
        
    end
    
    methods (Access = public)
        
        function ClearMessages(app)
            app.MessagesTextArea.Value=''; % clear messages
        end

        function TopMessage(app,message)
             app.MessagesTextArea.Value=vertcat(message, string(app.MessagesTextArea.Value)); % put new message on top
        end
        
        function referenceCoilDrive(app)
            offset=app.OffsetEditField.Value;
            gain=app.GainEditField.Value;
            i=app.CurrentListBox.Value;
            CD=uint16(999-(offset*10)-(gain*(i-1)));
            app.CDEditField.Value=double(CD);
            % Could add these for reference:
            % coildrivev=10.7-(coildrive/166.5);
            % de=decimal coildrive index,  he=hex coildrive, heInv=hex as entered in network test
            % de=uint16(699);he=dec2hex(de,4);heInv=[he(3:4),he(1:2)]

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin<2
                NNP=[];
            else
                NNP=varargin{1};
            end
           

            if isempty(NNP)
                app.nnp = NNPAPI();
                figure(app.UIFigure); %return focus after nnp opened if needed
                if isempty(app.nnp.port)
                    %msgbox('No port chosen for NNP Access Point')
                    uialert(app.UIFigure,'No port chosen for NNP Access Point','No NNP AP')
                    delete(app.nnp)
                    %                     return
                 else
                    app.noNNP=false;
                end
            else
                app.nnp = NNP;
                app.noNNP=false;
            end


            if nargin>2
                app.callingFigure=varargin{2};
            end
%             app.UITable.Data=app.coilDriveTableData;
            app.updateTimer=timer(); %start with default timer
            app.updateTimer.Period=app.ScanIntervalEditField.Value;
            app.updateTimer.ExecutionMode='fixedSpacing';
            app.updateTimer.BusyMode='drop'; %may want to change this to another option
            % app.updateTimer.BusyMode='drop'; %may want to change this to another option
            app.updateTimer.TimerFcn=@app.updateDisplays; %update the displays each timer cycle
        end

        % Value changed function: CurrentListBox
        function CurrentListBoxValueChanged(app, event)
            %Since I only allow change when manual is enabled, I can write
            %the index directly to the PM if NNP is active.
            value = app.CurrentListBox.Value;
            app.currentIndex=value;
            app.IntendedCurrentEditField.Value=app.currentArray(value);
%             app.chargeCurrent=app.currentArray(value);
          %  app.IntendedCurrentEditField.Value=double(app.currentArray(app.currentIndex));
            switch value
                case 1
                    app.OffsetSlider.Enable=true;
                    app.GainSlider.Enable=false;
                case 5
                    app.OffsetSlider.Enable=false;
                    app.GainSlider.Enable=true;
                otherwise
                    app.OffsetSlider.Enable=false;
                    app.GainSlider.Enable=false;
            end
            % app.CoilDriveEditField.Value=double(app.coilDriveTableData(app.currentIndex,2));
%             app.updateCoilDrive();
            % value will be an index from 1-5
            % set index based on which is selected
             if ~app.noNNP
               if  app.nnp.write(7,'1F57',147,uint8(app.currentIndex),'uint8') == 0
                   msg=sprintf('Override Index %d',app.currentIndex);
                   app.TopMessage(msg);
               else
                    if  app.nnp.write(7,'1F57',147,uint8(app.currentIndex),'uint8') == 0
                       msg=sprintf('Override Index %d',app.currentIndex);
                       app.TopMessage(msg);
                    else
                        app.TopMessage('Error starting manual mode');
                    end
               end 
               app.referenceCoilDrive();
             end
        end

        % Value changed function: OffsetSlider
        function OffsetSliderValueChanged(app, event)
            offset=round(app.OffsetSlider.Value);
            app.OffsetSlider.Value=offset; % snap to integer
            %update the charging table and CD output
%             gain=app.GainSlider.Value;
%              app.updateChargingTable(offset,gain);
            app.OffsetEditField.Value=offset;
%             app.GainEditField.Value=gain;
%             app.updateCoilDrive();
            if ~app.noNNP
               if  app.nnp.write(7,'1F57',145,uint8(offset),'uint8') == 0
                   msg=sprintf('Offset %d',offset);
                   app.TopMessage(msg);
               else
                   if  app.nnp.write(7,'1F57',145,uint8(offset),'uint8') == 0
                       msg=sprintf('Offset %d',offset);
                       app.TopMessage(msg);
                    else
                        app.TopMessage('Error setting offset');
                    end  
                end  
            end
            app.referenceCoilDrive();
        end

        % Value changed function: GainSlider
        function GainSliderValueChanged(app, event)
%            offset = round(app.OffsetSlider.Value);
            %update the charging table and CD output
            gain=round(app.GainSlider.Value);
            app.GainSlider.Value=gain; %snap to integer on slider
            app.GainEditField.Value=gain;
%             app.updateChargingTable(offset,gain);
%             app.updateCoilDrive();
            if ~app.noNNP
                if  app.nnp.write(7,'1F57',146,uint8(gain),'uint8') == 0
                   msg=sprintf('Gain %d',gain);
                   app.TopMessage(msg);
                else
                    if  app.nnp.write(7,'1F57',146,uint8(gain),'uint8') == 0
                        msg=sprintf('Gain %d',gain);
                         app.TopMessage(msg);
                    else
                        app.TopMessage('Error setting Gain');
                    end  
                end  
            end
            app.referenceCoilDrive();
        end

        % Button pushed function: ReadODButton
        function ReadODButtonPushed(app, event)
            app.readChargeOD3000();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
           if ishandle(app.callingFigure) % check that is is valid
                figure(app.callingFigure); % bring to front
           end
            delete(app)
            
        end

        % Button pushed function: WriteODButton
        function WriteODButtonPushed(app, event)
            app.writeChargeOD3000();
        end

        % Button pushed function: SaveODButton
        function SaveODButtonPushed(app, event)
            if ~app.noNNP
                if app.nnp.saveOD(7)
                    dmessage = 'PM OD Saved';
                else
                    dmessage = 'Failed PM OD Save';
                end
                app.TopMessage(dmessage);
            end
        end

        % Button pushed function: ClearMessageButton
        function ClearMessageButtonPushed(app, event)
            app.MessagesTextArea.Value=''; % clear messages
        end

        % Value changed function: ScanButton
        function ScanButtonValueChanged(app, event)
            value = app.ScanButton.Value;
            app.readChargeOD3000(); %update these first for reference
            if value %scanning
                app.readChargeOD3000(); %update these first for reference
                pause(0.1);
                app.ScanButton.BackgroundColor=[0,1,0];

                app.updateTimer.Period=app.ScanIntervalEditField.Value;
                app.ScanIntervalEditField.Enable = 'off';                
                app.startUpdate();  % start updateDisplay

            else
                app.ScanButton.BackgroundColor=[0.96,0.96,0.96];
                % stop updateDisplay
                app.stopUpdate();
                app.ScanIntervalEditField.Enable = 'on';

            end
        end

        % Value changed function: ManualOverrideButton
        function ManualOverrideButtonValueChanged(app, event)
            value = app.ManualOverrideButton.Value;
            if value
                app.ManualOverrideButton.BackgroundColor=[0,1,0];
                app.CurrentListBoxValueChanged(); %update the index
            else
                app.OffsetSlider.Enable=false;
                app.GainSlider.Enable=false;
                app.ManualOverrideButton.BackgroundColor=[0.96,0.96,0.96];
                if ~app.noNNP
                    if  app.nnp.write(7,'1F57',147,uint8(0),'uint8') == 0
                        app.TopMessage('Override disabled');
                    else
                        if app.nnp.write(7,'1F57',147,uint8(0),'uint8') == 0
                            app.TopMessage('Override message error');
                        else
                            app.TopMessage('Override disabled');
                        end
                    end
                end
            end
        
            app.IntendedCurrentEditField.Enable=value;
            app.CDEditField.Enable=value;
            app.CurrentListBox.Enable=value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SetChargingParametersLabel = uilabel(app.UIFigure);
            app.SetChargingParametersLabel.Text = 'Set Charging Parameters';
            app.CurrentListBoxLabel = uilabel(app.UIFigure);
            app.CurrentListBoxLabel.Enable = 'off';
            app.CurrentListBoxLabel.Text = 'Current';
            app.CurrentListBox = uilistbox(app.UIFigure);
            app.CurrentListBox.Items = {'55', '65', '75', '85', '95'};
            app.CurrentListBox.ItemsData = [1 2 3 4 5];
            app.CurrentListBox.ValueChangedFcn = createCallbackFcn(app, @CurrentListBoxValueChanged, true);
            app.CurrentListBox.Enable = 'off';
            app.CurrentListBox.Value = 1;
            app.GainSliderLabel = uilabel(app.UIFigure);
            app.GainSliderLabel.Enable = 'off';
            app.GainSliderLabel.Text = 'Gain';
            app.GainSlider = uislider(app.UIFigure);
            app.GainSlider.Limits = [0 60];
            app.GainSlider.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60];
            app.GainSlider.MajorTickLabels = {'0', '5', '10', '15', '20', '25', '30', '35', '40', '45', '50', '55', '60'};
            app.GainSlider.ValueChangedFcn = createCallbackFcn(app, @GainSliderValueChanged, true);
            app.GainSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60];
            app.GainSlider.Enable = 'off';
            app.GainSlider.Value = 50;
            app.CurrentPanel = uipanel(app.UIFigure);
            app.CurrentPanel.Title = 'Current';
            app.ManualOverrideButton = uibutton(app.CurrentPanel, 'state');
            app.ManualOverrideButton.ValueChangedFcn = createCallbackFcn(app, @ManualOverrideButtonValueChanged, true);
            app.ManualOverrideButton.Text = {'Manual'; 'Override'};
            app.IntendedCurrentLabel = uilabel(app.CurrentPanel);
            app.IntendedCurrentLabel.Enable = 'off';
            app.IntendedCurrentLabel.Tooltip = {'to 3000.0B'};
            app.IntendedCurrentLabel.Text = {'Intended'; 'Current'};
            app.IntendedCurrentEditField = uieditfield(app.CurrentPanel, 'numeric');
            app.IntendedCurrentEditField.ValueDisplayFormat = '%.0f';
            app.IntendedCurrentEditField.Enable = 'off';
            app.IntendedCurrentEditField.Value = 55;
            app.CDLabel = uilabel(app.CurrentPanel);
            app.CDLabel.Enable = 'off';
            app.CDLabel.Text = 'CD ';
            app.CDEditField = uieditfield(app.CurrentPanel, 'numeric');
            app.CDEditField.Enable = 'off';
            app.ChargeParametersPanel = uipanel(app.UIFigure);
            app.ChargeParametersPanel.Title = 'ChargeParameters';
            app.ChargeIntervalEditFieldLabel = uilabel(app.ChargeParametersPanel);
            app.ChargeIntervalEditFieldLabel.Text = 'Charge Interval';
            app.ChargeIntervalEditField = uieditfield(app.ChargeParametersPanel, 'numeric');
            app.ChargeIntervalEditField.ValueDisplayFormat = '%.0f';
            app.ChargeIntervalEditField.Value = 2;
            app.StepIncrementEditFieldLabel = uilabel(app.ChargeParametersPanel);
            app.StepIncrementEditFieldLabel.Text = 'Step Increment';
            app.StepIncrementEditField = uieditfield(app.ChargeParametersPanel, 'numeric');
            app.StepIncrementEditField.ValueDisplayFormat = '%.0f';
            app.StepIncrementEditField.Value = 2;
            app.NoChargeLimitEditFieldLabel = uilabel(app.ChargeParametersPanel);
            app.NoChargeLimitEditFieldLabel.Text = 'No Charge Limit';
            app.NoChargeLimitEditField = uieditfield(app.ChargeParametersPanel, 'numeric');
            app.NoChargeLimitEditField.ValueDisplayFormat = '%.0f';
            app.NoChargeLimitEditField.Value = 80;
            app.NoChargeStepLimitEditFieldLabel = uilabel(app.ChargeParametersPanel);
            app.NoChargeStepLimitEditFieldLabel.Text = 'No Charge Step Limit';
            app.NoChargeStepLimitEditField = uieditfield(app.ChargeParametersPanel, 'numeric');
            app.NoChargeStepLimitEditField.ValueDisplayFormat = '%.0f';
            app.NoChargeStepLimitEditField.Value = 120;
            app.OffsetSliderLabel = uilabel(app.UIFigure);
            app.OffsetSliderLabel.Enable = 'off';
            app.OffsetSliderLabel.Text = 'Offset';
            app.OffsetSlider = uislider(app.UIFigure);
            app.OffsetSlider.Limits = [0 50];
            app.OffsetSlider.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50];
            app.OffsetSlider.MajorTickLabels = {'0', '5', '10', '15', '20', '25', '30', '35', '40', '45', '50'};
            app.OffsetSlider.ValueChangedFcn = createCallbackFcn(app, @OffsetSliderValueChanged, true);
            app.OffsetSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50];
            app.OffsetSlider.Enable = 'off';
            app.OffsetSlider.Value = 12;
            app.PMODPanel = uipanel(app.UIFigure);
            app.PMODPanel.Title = 'PM OD';
            app.ReadODButton = uibutton(app.PMODPanel, 'push');
            app.ReadODButton.ButtonPushedFcn = createCallbackFcn(app, @ReadODButtonPushed, true);
            app.ReadODButton.Text = 'Read OD';
            app.WriteODButton = uibutton(app.PMODPanel, 'push');
            app.WriteODButton.ButtonPushedFcn = createCallbackFcn(app, @WriteODButtonPushed, true);
            app.WriteODButton.Tooltip = {'Write 3000 Charge Par'};
            app.WriteODButton.Text = 'Write OD';
            app.SaveODButton = uibutton(app.PMODPanel, 'push');
            app.SaveODButton.ButtonPushedFcn = createCallbackFcn(app, @SaveODButtonPushed, true);
            app.SaveODButton.Text = 'Save OD';
            app.VrecGaugeLabel = uilabel(app.UIFigure);
            app.VrecGaugeLabel.Text = 'Vrec';
            app.VrecGauge = uigauge(app.UIFigure, 'linear');
            app.VrecGauge.Limits = [0 30];
            app.OffsetEditFieldLabel = uilabel(app.UIFigure);
            app.OffsetEditFieldLabel.Visible = 'off';
            app.OffsetEditFieldLabel.Text = 'Offset';
            app.OffsetEditField = uieditfield(app.UIFigure, 'numeric');
            app.OffsetEditField.ValueDisplayFormat = '%.0f';
            app.OffsetEditField.Value = 12;
            app.GainEditFieldLabel = uilabel(app.UIFigure);
            app.GainEditFieldLabel.Visible = 'off';
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.UIFigure, 'numeric');
            app.GainEditField.ValueDisplayFormat = '%.0f';
            app.GainEditField.Value = 50;
            app.MessagesTextAreaLabel = uilabel(app.UIFigure);
            app.MessagesTextAreaLabel.Text = 'Messages';
            app.MessagesTextArea = uitextarea(app.UIFigure);
            app.ClearMessageButton = uibutton(app.UIFigure, 'push');
            app.ClearMessageButton.ButtonPushedFcn = createCallbackFcn(app, @ClearMessageButtonPushed, true);
            app.ClearMessageButton.Text = 'clear';
            app.ReadValuesPanel = uipanel(app.UIFigure);
            app.ReadValuesPanel.Title = 'Read Values';
            app.VrecEditFieldLabel = uilabel(app.ReadValuesPanel);
            app.VrecEditFieldLabel.Text = 'Vrec';
            app.VrecEditField = uieditfield(app.ReadValuesPanel, 'numeric');
            app.VrecEditField.ValueDisplayFormat = '%.0f';
            app.ActualCurrentEditFieldLabel = uilabel(app.ReadValuesPanel);
            app.ActualCurrentEditFieldLabel.Text = 'Actual Current';
            app.ActualCurrentEditField = uieditfield(app.ReadValuesPanel, 'numeric');
            app.ReqCurrentEditFieldLabel = uilabel(app.ReadValuesPanel);
            app.ReqCurrentEditFieldLabel.Tooltip = {'to 3000.0B'};
            app.ReqCurrentEditFieldLabel.Text = 'Req Current';
            app.ReqCurrentEditField = uieditfield(app.ReadValuesPanel, 'numeric');
            app.ReqCurrentEditField.ValueDisplayFormat = '%.0f';
            app.ReqCurrentEditField.Value = 55;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Manual override will bypass the temperature reading '; 'and set the index for the smart charging current.  '; 'The offset and gain levels may then be set to produce the desired Vrec at that current level.'};
            app.ScanPanel = uipanel(app.UIFigure);
            app.ScanPanel.Title = 'Scan';
            app.ScanButton = uibutton(app.ScanPanel, 'state');
            app.ScanButton.ValueChangedFcn = createCallbackFcn(app, @ScanButtonValueChanged, true);
            app.ScanButton.Text = 'Scan';
            app.ScanLED = uilamp(app.ScanPanel);
            app.scanIntLabel = uilabel(app.ScanPanel);
            app.scanIntLabel.Text = 'scan Int';
            app.ScanIntervalEditField = uieditfield(app.ScanPanel, 'numeric');
            app.ScanIntervalEditField.ValueDisplayFormat = '%.1f';
            app.ScanIntervalEditField.Value = 0.5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = setChargeParameters(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end