classdef setSmartChargingLevels < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SlopeLockCheckBox             matlab.ui.control.CheckBox
        MaxEditFieldLabel             matlab.ui.control.Label
        MaxUpButton                   matlab.ui.control.Button
        MaxEditField                  matlab.ui.control.NumericEditField
        MaxDownButton                 matlab.ui.control.Button
        ClearMessageButton            matlab.ui.control.Button
        MessagesTextArea              matlab.ui.control.TextArea
        MessagesTextAreaLabel         matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        SetChargingLevelsLabel        matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        ChargeParametersPanel         matlab.ui.container.Panel
        SaveODButton                  matlab.ui.control.Button
        WriteODButton                 matlab.ui.control.Button
        ReadODButton                  matlab.ui.control.Button
        NoChargeStepLimitEditField    matlab.ui.control.NumericEditField
        NoChargeStepLimitEditFieldLabel  matlab.ui.control.Label
        NoChargeLimitEditField        matlab.ui.control.NumericEditField
        NoChargeLimitEditFieldLabel   matlab.ui.control.Label
        StepIncrementEditField        matlab.ui.control.NumericEditField
        StepIncrementEditFieldLabel   matlab.ui.control.Label
        ChargeIntervalEditField       matlab.ui.control.NumericEditField
        ChargeIntervalEditFieldLabel  matlab.ui.control.Label
        CurrentLevelPanel             matlab.ui.container.Panel
        ManualButton                  matlab.ui.control.StateButton
        Button95                      matlab.ui.control.StateButton
        Button85                      matlab.ui.control.StateButton
        Button75                      matlab.ui.control.StateButton
        Button65                      matlab.ui.control.StateButton
        Button55                      matlab.ui.control.StateButton
        SlopeDownButton               matlab.ui.control.Button
        SlopeUpButton                 matlab.ui.control.Button
        OffsetDownButton              matlab.ui.control.Button
        OffsetUpButton                matlab.ui.control.Button
        SlopeEditField                matlab.ui.control.NumericEditField
        SlopeEditFieldLabel           matlab.ui.control.Label
        OffsetEditField               matlab.ui.control.NumericEditField
        OffsetEditFieldLabel          matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        nnp=[]; % nnpapi
        noNNP=true; % add this so that I can run the app when disconnected
        coilDriveIndexArray=[729 676 623 570 517]; % Coil drive setting in object dictionary
        coilDriveVoltsArray=[6.3 6.6 7.0 7.3 7.6]; % Drive voltage for plot
        chargeCurrent=[55 65 75 85 95]; % charge current for plots
        SmartChargeParameters=[27 53 0]; % defaults offset/10, slope, manual current level
        callingFigure=[];
    end
    
    methods (Access = private)
        
        function updateGraph(app)
           
            % update the coil drive graph
            % read the low and high values
            % calculate the offset and step
            offset=999-(app.OffsetEditField.Value*10);
            step=app.SlopeEditField.Value;
            app.SmartChargeParameters(1)=app.OffsetEditField.Value;
            app.SmartChargeParameters(2)=app.SlopeEditField.Value;
             % calculate coilDriveIndex array
            app.coilDriveIndexArray(1)=offset;

            for i=2:5
                 app.coilDriveIndexArray(i)=app.coilDriveIndexArray(i-1)-step;
            end
            % send values to Object Dictionary of PM

            app.SCPtoPM();

            % convert coilDriveIndex array to coilDriveVolts array
            for i=1:5
                   app.coilDriveVoltsArray(i)=10.7-(app.coilDriveIndexArray(i)/166.5);
            end
            % plot voltage graph
            pLine=plot(app.UIAxes,app.chargeCurrent,app.coilDriveVoltsArray); %plot line
            pLine.Marker='.';
            pLine.MarkerSize=30;
            grid(app.UIAxes,'on');
        end
        
        function setRadioButtons(app)
             x=app.SmartChargeParameters(3);
            app.Button55.Value=(x==1);
            app.Button65.Value=(x==2);
            app.Button75.Value=(x==3);
            app.Button85.Value=(x==4);
            app.Button95.Value=(x==5);
            % I could also change the button colors here
        end
    end
    
    methods (Access = public)
        
       function ClearMessages(app)
            app.MessagesTextArea.Value=''; % clear messages
        end

        function TopMessage(app,message)
             app.MessagesTextArea.Value=vertcat(message, string(app.MessagesTextArea.Value)); % put new message on top
        end
       function readChargeOD3000(app)
            if ~app.noNNP
                 resp=app.nnp.read(7,'3000',2,11,'uint8');%read indices 2-0xC as bytes
                         %assuming starting with index 2:
%              Subindex	start	bytes	type	variable
%              2	    1	    1	uint8	BatteryControl_BatteryChargingStepIncrement
%              3	    2	    2	uint16	BatteryControl_BatteryChargingInterval
%              4	    4	    2	uint16	BatteryControl_ChargeNFLinkNoChargeLimit
%              5	    6	    2	uint16	BatteryControl_ChargeNFLinkNoChargeStepLimit
%              6        8       1   uint8   BatteryChargeRun
%              7	    9	    2	uint16	BatteryControl_VREC*10
%              8        11	    2	uint16	VSYS
%              9        13	    2	uint16	VNET
%              10       15	    2	uint16	LOAD
%              11	    17	    1	uint8	BatteryControl Requested Charging Current
%              12	    18	    1	uint8	BatteryControl Commanded Charging Current
                if(length(resp)==18) %valid packet
                    % update values displayed
                    app.StepIncrementEditField.Value=double(resp(1));
                    app.ChargeIntervalEditField.Value=double(typecast(resp([2 3]),'uint16'));
                    app.NoChargeLimitEditField.Value=double(typecast(resp([4 5]),'uint16'));
                    app.NoChargeStepLimitEditField.Value=double(typecast(resp([6 7]),'uint16'));
                  % app.VrecEditField.Value=double(typecast(resp([9 10]),'uint16'))/10;
                  % app.VrecGauge.Value=double(typecast(resp([9 10]),'uint16'))/10; 
                  % app.ReqCurrentEditField.Value=double(resp(17));
                  % app.ActualCurrentEditField.Value=double(resp(18));
                    
                else
    %
                end
                resp=app.nnp.read(7,'1F57',145,3,'uint8');
                if(length(resp)==3) %valid packet
                    resp=double(resp); % since values require doubles
                    app.SmartChargeParameters=double(resp); %need to check data types here
                    app.OffsetEditField.Value=app.SmartChargeParameters(1);                
                    app.SlopeEditField.Value=app.SmartChargeParameters(2);
                    
                    % if the index is between 1 and 5
                    % set manual to true and run manual change callback
                end
                app.updateGraph();
            end

        end
        
        function writeChargeOD3000(app)
            % write back the appropriate parameters to PM OD 3000
                         %assuming starting with index 2:
%              Subindex	start	bytes	type	variable
%              2	    1	    1	uint8	BatteryControl_BatteryChargingStepIncrement
%              3	    2	    2	uint16	BatteryControl_BatteryChargingInterval
%              4	    4	    2	uint16	BatteryControl_ChargeNFLinkNoChargeLimit
%              5	    6	    2	uint16	BatteryControl_ChargeNFLinkNoChargeStepLimit
%              6        8       1   uint8   BatteryChargeRun
%              7	    9	    2	uint16	BatteryControl_VREC*10
%              8        11	    2	uint16	VSYS
%              9        13	    2	uint16	VNET
%              10       15	    2	uint16	LOAD
%              11	    17	    1	uint8	BatteryControl Requested Charging Current
%              12	    18	    1	uint8	BatteryControl Commanded Charging Current
            % unlike the read, I am only going to write back four elements
% There may be a way to write this back as a block, but this was the safer way
            if ~app.noNNP
               if app.nnp.write(7,'3000',2,uint8(app.StepIncrementEditField.Value),'uint8')== 0
                    app.TopMessage('Step Increment');
               end
               if app.nnp.write(7,'3000',3,uint16(app.ChargeIntervalEditField.Value),'uint16')== 0
                   app.TopMessage('Charge Interval');
               end
               if app.nnp.write(7,'3000',4,uint16(app.NoChargeLimitEditField.Value),'uint16') == 0
                   app.TopMessage('No Charge Limit');
               end
              if  app.nnp.write(7,'3000',5,uint16(app.NoChargeStepLimitEditField.Value),'uint16') == 0
                   app.TopMessage('No Step Limit');
              end
            
              app.TopMessage('--');
              app.SCPtoPM();
            end
        end
        
        function SCPtoPM(app)
             if ~app.noNNP
                 if  app.nnp.write(7,'1F57',145,uint8(app.SmartChargeParameters),'uint8',3) == 0 %write as a block
                       msg='SMC to PM';
                       app.TopMessage(msg);
                    else
                       app.TopMessage('Error setting SCP');                    
                 end  
             end
        end
        
        function RoundSlope(app)
            % Look at offset and max, then round slope and reset Max
            % slope = round(((max-(offset*10))/4)
            if ~app.SlopeLockCheckBox.Value
                app.SlopeEditField.Value=round((app.MaxEditField.Value-(app.OffsetEditField.Value*10))/4);
            end
            app.MaxEditField.Value=(app.OffsetEditField.Value*10)+(4*app.SlopeEditField.Value);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
          if nargin<2
                NNP=[];
            else
                NNP=varargin{1};
           end
           

            if isempty(NNP)
                app.nnp = NNPAPI();
                figure(app.UIFigure); %return focus after nnp opened if needed
                if isempty(app.nnp.port)
                    %msgbox('No port chosen for NNP Access Point')
                    uialert(app.UIFigure,'No port chosen for NNP Access Point','No NNP AP')
                    delete(app.nnp)
                    %                     return
                 else
                    app.noNNP=false;
                end
            else
                app.nnp = NNP;
                app.noNNP=false;
            end


            if nargin>2
                app.callingFigure=varargin{2};
            end
            app.updateGraph();
        end

        % Value changed function: OffsetEditField
        function OffsetEditFieldValueChanged(app, event)
            value = app.OffsetEditField.Value; % left here in case I want to do range checking
            app.RoundSlope(); % round slope to ensure offset and max match
            app.updateGraph();
        end

        % Value changed function: SlopeEditField
        function SlopeEditFieldValueChanged(app, event)
            value = app.SlopeEditField.Value;% left here in case I want to do range checking
            app.MaxEditField.Value=(app.OffsetEditField.Value*10)+(4*app.SlopeEditField.Value);
            app.updateGraph();
        end

        % Button pushed function: OffsetUpButton
        function OffsetUpButtonPushed(app, event)
           app.OffsetEditField.Value=app.OffsetEditField.Value+1; 
           app.OffsetEditFieldValueChanged();
        end

        % Button pushed function: OffsetDownButton
        function OffsetDownButtonPushed(app, event)
           if app.OffsetEditField.Value>=1
               app.OffsetEditField.Value=app.OffsetEditField.Value-1; 
               app.OffsetEditFieldValueChanged();
           end
        end

        % Button pushed function: SlopeUpButton
        function SlopeUpButtonPushed(app, event)
            app.SlopeEditField.Value=app.SlopeEditField.Value+1;
            app.SlopeEditFieldValueChanged();
        end

        % Button pushed function: SlopeDownButton
        function SlopeDownButtonPushed(app, event)
            if  app.SlopeEditField.Value>=1
                 app.SlopeEditField.Value= app.SlopeEditField.Value-1;
                 app.SlopeEditFieldValueChanged();
            end
        end

        % Value changed function: ManualButton
        function ManualButtonValueChanged(app, event)
            value = app.ManualButton.Value;
            if value
                app.SaveODButton.Enable=false;
                app.Button55ValueChanged();
 %               app.SaveODButton.BackgroundColor=[.94,.94,.94];
                app.SmartChargeParameters(3)=1;
                app.ManualButton.BackgroundColor=[0 1 0];
                app.CurrentLevelPanel.BackgroundColor=[0.94,1,0.94];
            else
                app.SmartChargeParameters(3)=0;
                app.ManualButton.BackgroundColor=[0.94,0.94,0.94];
                app.CurrentLevelPanel.BackgroundColor=[0.94,0.94,0.94];
                app.SaveODButton.Enable=true;
              %  app.SaveODButton.BackgroundColor=[.94,1,.94];
            end
            app.setRadioButtons();
            app.Button55.Enable=value;
            app.Button65.Enable=value;
            app.Button75.Enable=value;
            app.Button85.Enable=value;
            app.Button95.Enable=value;

        end

        % Value changed function: Button55
        function Button55ValueChanged(app, event)
            value = app.Button55.Value;
            app.SmartChargeParameters(3)=1;
            app.setRadioButtons();
            app.updateGraph();

        end

        % Value changed function: Button65
        function Button65ValueChanged(app, event)
            value = app.Button65.Value;
            app.SmartChargeParameters(3)=2;
            app.setRadioButtons();
            app.updateGraph();
        end

        % Value changed function: Button75
        function Button75ValueChanged(app, event)
            value = app.Button75.Value;
            app.SmartChargeParameters(3)=3;
            app.setRadioButtons();
            app.updateGraph();
        end

        % Value changed function: Button85
        function Button85ValueChanged(app, event)
            value = app.Button85.Value;
            app.SmartChargeParameters(3)=4;
            app.setRadioButtons();
            app.updateGraph();
        end

        % Value changed function: Button95
        function Button95ValueChanged(app, event)
            value = app.Button95.Value;
            app.SmartChargeParameters(3)=5;
            app.setRadioButtons();
            app.updateGraph();
        end

        % Button pushed function: SaveODButton
        function SaveODButtonPushed(app, event)
           if ~app.noNNP
                 result=app.nnp.write(7,'1F57',147,uint8(0),'uint8'); % write 0 to manual mode first, then save OD
                 if isequal(result,0)  % This way the manual mode is not saved
                    if app.nnp.saveOD(7)
                        app.SaveODButton.BackgroundColor=[.94,1,.94];
                        dmessage = 'PM OD Saved';
                    else
                        dmessage = 'Failed PM OD Save NMT';
                        app.SaveODButton.BackgroundColor=[1,.94,.94];
                    end                
                else
                    dmessage = 'Writing to 1F57.93';
                    app.SaveODButton.BackgroundColor=[1,.94,.94];
                end
                app.TopMessage(dmessage);
                
                pause (1)
                app.SaveODButton.BackgroundColor=[.94,.94,.94];% return to normal color
            end 
        end

        % Button pushed function: MaxUpButton
        function MaxUpButtonPushed(app, event)
           if ~app.SlopeLockCheckBox.Value
               app.MaxEditField.Value=app.MaxEditField.Value+4;  
           else
               app.OffsetUpButtonPushed();
           end
          app.MaxEditFieldValueChanged();
        end

        % Button pushed function: MaxDownButton
        function MaxDownButtonPushed(app, event)
        if ~app.SlopeLockCheckBox.Value
            app.MaxEditField.Value=app.MaxEditField.Value-4; 
        else
            app.OffsetDownButtonPushed()
        end
         app.MaxEditFieldValueChanged();
        end

        % Value changed function: MaxEditField
        function MaxEditFieldValueChanged(app, event)
         value = app.MaxEditField.Value;         
         app.RoundSlope(); % round slope to ensure offset and max match
         app.updateGraph();   
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ishandle(app.callingFigure) % check that is is valid
                figure(app.callingFigure); % bring to front
            end
            delete(app)
            
        end

        % Button pushed function: ReadODButton
        function ReadODButtonPushed(app, event)
             app.readChargeOD3000();
        end

        % Button pushed function: WriteODButton
        function WriteODButtonPushed(app, event)
            app.writeChargeOD3000();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Coil Output vs Charging Current')
            xlabel(app.UIAxes, 'Current, mA')
            ylabel(app.UIAxes, 'Coil Output, Volts')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [50 100];
            app.UIAxes.YLim = [4.5 8];
            app.UIAxes.XTick = [55 65 75 85 95];
            app.UIAxes.XTickLabel = {'55'; '65'; '75'; '85'; '95'};
            app.OffsetEditFieldLabel = uilabel(app.UIFigure);
            app.OffsetEditFieldLabel.Text = 'Offset';
            app.OffsetEditField = uieditfield(app.UIFigure, 'numeric');
            app.OffsetEditField.ValueChangedFcn = createCallbackFcn(app, @OffsetEditFieldValueChanged, true);
            app.OffsetEditField.Value = 27;
            app.SlopeEditFieldLabel = uilabel(app.UIFigure);
            app.SlopeEditFieldLabel.Text = 'Slope';
            app.SlopeEditField = uieditfield(app.UIFigure, 'numeric');
            app.SlopeEditField.ValueChangedFcn = createCallbackFcn(app, @SlopeEditFieldValueChanged, true);
            app.SlopeEditField.Value = 53;
            app.OffsetUpButton = uibutton(app.UIFigure, 'push');
            app.OffsetUpButton.ButtonPushedFcn = createCallbackFcn(app, @OffsetUpButtonPushed, true);
            app.OffsetUpButton.Text = '▲';
            app.OffsetDownButton = uibutton(app.UIFigure, 'push');
            app.OffsetDownButton.ButtonPushedFcn = createCallbackFcn(app, @OffsetDownButtonPushed, true);
            app.OffsetDownButton.Text = '▼';
            app.SlopeUpButton = uibutton(app.UIFigure, 'push');
            app.SlopeUpButton.ButtonPushedFcn = createCallbackFcn(app, @SlopeUpButtonPushed, true);
            app.SlopeUpButton.Text = '▲';
            app.SlopeDownButton = uibutton(app.UIFigure, 'push');
            app.SlopeDownButton.ButtonPushedFcn = createCallbackFcn(app, @SlopeDownButtonPushed, true);
            app.SlopeDownButton.Text = '▼';
            app.CurrentLevelPanel = uipanel(app.UIFigure);
            app.CurrentLevelPanel.Title = 'Current Level';
            app.Button55 = uibutton(app.CurrentLevelPanel, 'state');
            app.Button55.ValueChangedFcn = createCallbackFcn(app, @Button55ValueChanged, true);
            app.Button55.Enable = 'off';
            app.Button55.Tooltip = {'<37..5°C'};
            app.Button55.Text = '55';
            app.Button65 = uibutton(app.CurrentLevelPanel, 'state');
            app.Button65.ValueChangedFcn = createCallbackFcn(app, @Button65ValueChanged, true);
            app.Button65.Enable = 'off';
            app.Button65.Tooltip = {'37.5-38°C'};
            app.Button65.Text = '65';
            app.Button75 = uibutton(app.CurrentLevelPanel, 'state');
            app.Button75.ValueChangedFcn = createCallbackFcn(app, @Button75ValueChanged, true);
            app.Button75.Enable = 'off';
            app.Button75.Tooltip = {'38-38.5°C'};
            app.Button75.Text = '75';
            app.Button85 = uibutton(app.CurrentLevelPanel, 'state');
            app.Button85.ValueChangedFcn = createCallbackFcn(app, @Button85ValueChanged, true);
            app.Button85.Enable = 'off';
            app.Button85.Tooltip = {'38.5-39°C'};
            app.Button85.Text = '85';
            app.Button95 = uibutton(app.CurrentLevelPanel, 'state');
            app.Button95.ValueChangedFcn = createCallbackFcn(app, @Button95ValueChanged, true);
            app.Button95.Enable = 'off';
            app.Button95.Tooltip = {'39-40°C'};
            app.Button95.Text = '95';
            app.ManualButton = uibutton(app.CurrentLevelPanel, 'state');
            app.ManualButton.ValueChangedFcn = createCallbackFcn(app, @ManualButtonValueChanged, true);
            app.ManualButton.Tooltip = {'Whether in manual or auto mode, the smart charging app in the control tower sends the charing current level command to the power module.'};
            app.ManualButton.Text = 'Manual';
            app.ChargeParametersPanel = uipanel(app.UIFigure);
            app.ChargeParametersPanel.Title = 'ChargeParameters';
            app.ChargeIntervalEditFieldLabel = uilabel(app.ChargeParametersPanel);
            app.ChargeIntervalEditFieldLabel.Text = 'Charge Interval';
            app.ChargeIntervalEditField = uieditfield(app.ChargeParametersPanel, 'numeric');
            app.ChargeIntervalEditField.ValueDisplayFormat = '%.0f';
            app.ChargeIntervalEditField.Tooltip = {'How often to step, in 100ms increments'};
            app.ChargeIntervalEditField.Value = 2;
            app.StepIncrementEditFieldLabel = uilabel(app.ChargeParametersPanel);
            app.StepIncrementEditFieldLabel.Text = 'Step Increment';
            app.StepIncrementEditField = uieditfield(app.ChargeParametersPanel, 'numeric');
            app.StepIncrementEditField.ValueDisplayFormat = '%.0f';
            app.StepIncrementEditField.Tooltip = {'How many mA to increase per step'};
            app.StepIncrementEditField.Value = 2;
            app.NoChargeLimitEditFieldLabel = uilabel(app.ChargeParametersPanel);
            app.NoChargeLimitEditFieldLabel.Text = 'No Charge Limit';
            app.NoChargeLimitEditField = uieditfield(app.ChargeParametersPanel, 'numeric');
            app.NoChargeLimitEditField.ValueDisplayFormat = '%.0f';
            app.NoChargeLimitEditField.Tooltip = {'belowe this level Vrec (div by 10) the current will be 0'};
            app.NoChargeLimitEditField.Value = 80;
            app.NoChargeStepLimitEditFieldLabel = uilabel(app.ChargeParametersPanel);
            app.NoChargeStepLimitEditFieldLabel.Text = 'No Charge Step Limit';
            app.NoChargeStepLimitEditField = uieditfield(app.ChargeParametersPanel, 'numeric');
            app.NoChargeStepLimitEditField.ValueDisplayFormat = '%.0f';
            app.NoChargeStepLimitEditField.Tooltip = {'Below this level (div by 10) the current will not increase'};
            app.NoChargeStepLimitEditField.Value = 120;
            app.ReadODButton = uibutton(app.ChargeParametersPanel, 'push');
            app.ReadODButton.ButtonPushedFcn = createCallbackFcn(app, @ReadODButtonPushed, true);
            app.ReadODButton.Text = 'Read OD';
            app.WriteODButton = uibutton(app.ChargeParametersPanel, 'push');
            app.WriteODButton.ButtonPushedFcn = createCallbackFcn(app, @WriteODButtonPushed, true);
            app.WriteODButton.Tooltip = {'Write 3000 Charge Par'};
            app.WriteODButton.Text = 'Write OD';
            app.SaveODButton = uibutton(app.ChargeParametersPanel, 'push');
            app.SaveODButton.ButtonPushedFcn = createCallbackFcn(app, @SaveODButtonPushed, true);
            app.SaveODButton.Text = {'Save'; 'OD'};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {'Manual override will bypass the temperature reading and set the current level.'; 'The coil drive can then be set to produce the best Vrec for that current'};
            app.SetChargingLevelsLabel = uilabel(app.UIFigure);
            app.SetChargingLevelsLabel.Text = 'Set Charging Levels';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = {'From the control tower display, observe the Vrec and current levels.'; ''; 'Adjust the coil drive until the Vrec is within the 12-18v range.'; 'Test at 55mA and 95mA levels.'; ''};
            app.MessagesTextAreaLabel = uilabel(app.UIFigure);
            app.MessagesTextAreaLabel.Text = 'Messages';
            app.MessagesTextArea = uitextarea(app.UIFigure);
            app.ClearMessageButton = uibutton(app.UIFigure, 'push');
            app.ClearMessageButton.Text = 'clear';
            app.MaxDownButton = uibutton(app.UIFigure, 'push');
            app.MaxDownButton.ButtonPushedFcn = createCallbackFcn(app, @MaxDownButtonPushed, true);
            app.MaxDownButton.Text = '▼';
            app.MaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxEditField.ValueChangedFcn = createCallbackFcn(app, @MaxEditFieldValueChanged, true);
            app.MaxEditField.Value = 482;
            app.MaxUpButton = uibutton(app.UIFigure, 'push');
            app.MaxUpButton.ButtonPushedFcn = createCallbackFcn(app, @MaxUpButtonPushed, true);
            app.MaxUpButton.Text = '▲';
            app.MaxEditFieldLabel = uilabel(app.UIFigure);
            app.MaxEditFieldLabel.Text = 'Max';
            app.SlopeLockCheckBox = uicheckbox(app.UIFigure);
            app.SlopeLockCheckBox.Tooltip = {'Lock the slope and change offset and max together'};
            app.SlopeLockCheckBox.Text = '';
            app.SlopeLockCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = setSmartChargingLevels(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end